classdef Ask_For_SaveName < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SetNameUIFigure         matlab.ui.Figure
        SetNameButton           matlab.ui.control.Button
        SaveNameEditField       matlab.ui.control.EditField
        SaveNameEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        CallerApp = [] % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, CallerApp)
            movegui(app.SetNameUIFigure, 'center');
            
            app = Utility_Change_Light_Dark_Mode(app,"Ask_SaveName");

            app.CallerApp = CallerApp;
            
            app.CallerApp.SaveName = [];
            
        end

        % Value changed function: SaveNameEditField
        function SaveNameEditFieldValueChanged(app, event)
            
        end

        % Button pushed function: SetNameButton
        function SetNameButtonPushed(app, event)
            app.CallerApp.SaveName = app.SaveNameEditField.Value;
            delete(app)
        end

        % Close request function: SetNameUIFigure
        function SetNameUIFigureCloseRequest(app, event)
            delete(app)
            app.CallerApp.SaveName = [];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SetNameUIFigure = uifigure('Visible', 'off');
            app.SetNameUIFigure.Name = 'Set Name';
            app.SetNameUIFigure.Icon = 'Logo.png';
            app.SetNameUIFigure.CloseRequestFcn = createCallbackFcn(app, @SetNameUIFigureCloseRequest, true);
            app.SaveNameEditFieldLabel = uilabel(app.SetNameUIFigure);
            app.SaveNameEditFieldLabel.Text = 'Save Name';
            app.SaveNameEditField = uieditfield(app.SetNameUIFigure, 'text');
            app.SaveNameEditField.ValueChangedFcn = createCallbackFcn(app, @SaveNameEditFieldValueChanged, true);
            app.SetNameButton = uibutton(app.SetNameUIFigure, 'push');
            app.SetNameButton.ButtonPushedFcn = createCallbackFcn(app, @SetNameButtonPushed, true);
            app.SetNameButton.Text = 'Set Name';
            % Show the figure after all components are created
            app.SetNameUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Ask_For_SaveName(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SetNameUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SetNameUIFigure)
        end
    end
end