classdef Event_Indice_Rejection_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DeleteTriggerWindowUIFigure     matlab.ui.Figure
        ChangeTimeWindowAroundTriggerMenu  matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        RightPanel                      matlab.ui.container.Panel
        DeleteSelectedEventButton       matlab.ui.control.Button
        RejectionOptionsLabel           matlab.ui.control.Label
        CloseButton                     matlab.ui.control.Button
        InformationTextArea             matlab.ui.control.TextArea
        InformationTextAreaLabel        matlab.ui.control.Label
        RejectionOptionsPanel           matlab.ui.container.Panel
        DataToExtractFromDropDown       matlab.ui.control.DropDown
        DataToExtractFromDropDownLabel  matlab.ui.control.Label
        TriggerToRejectMatlabExpressionsEditField  matlab.ui.control.EditField
        TriggerToRejectMatlabExpressionsLabel  matlab.ui.control.Label
        EventstoPlotDropDown            matlab.ui.control.DropDown
        EventstoPlotLabel               matlab.ui.control.Label
        EventChannelforStimulationDropDown  matlab.ui.control.DropDown
        EventChannelofStimulationLabel  matlab.ui.control.Label
        Slider                          matlab.ui.control.Slider
        DataLineSpacingSliderLabel      matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        ContextMenu                     matlab.ui.container.ContextMenu
        ChangeChannelSpacingLimitsMenu  matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        PreproApp = [] % Description
        PlotTimeVector = [];
        StimArtefactInfo = []
        Mainapp = [];
        EventTime = [];
        TimearoundEvent = [];
        ColorMap = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            %% Initiate GUI 
            movegui(app.DeleteTriggerWindowUIFigure, 'center');

            app.Mainapp = Mainapp;
            
            app = Utility_Change_Light_Dark_Mode(app,"DeleteTriggerIndices");

            %% -------------------- Set Up GUI Fields -------------------- 
            app.Slider.Limits = app.Mainapp.Slider.Limits;
            app.Slider.Value = app.Mainapp.Slider.Value;
            
            app.ColorMap = parula(size(app.Mainapp.Data.Raw,1));

            if isfield(app.Mainapp.Data,'Preprocessed')
                app.DataToExtractFromDropDown.Items{1} = 'Raw Data';
                app.DataToExtractFromDropDown.Items{2} = 'Preprocessed Data';
            else
                app.DataToExtractFromDropDown.Items{1} = 'Raw Data';
            end

            for neventchannel = 1:length(app.Mainapp.Data.Events)
                app.EventChannelforStimulationDropDown.Items{neventchannel} = app.Mainapp.Data.Info.EventChannelNames{neventchannel};
            end

            %% -------------------- setup time --------------------
            app.TimearoundEvent(1) = 0.5;
            app.TimearoundEvent(2) = 0.5;

             %% -------------------- Extract Event Related Data --------------------
            [app.Mainapp.Data,~] = Event_Module_Extract_Event_Related_Data(app.Mainapp.Data,app.EventChannelforStimulationDropDown.Value,app.TimearoundEvent,app.DataToExtractFromDropDown.Value,"Raw Event Related Data");
            
            %% -------------------- Setup trigger --------------------
            app.EventstoPlotDropDown.Items{1} = 'Mean over all Trigger';

            for i = 1:size(app.Mainapp.Data.EventRelatedData,2)
                app.EventstoPlotDropDown.Items{i+1} = num2str(i);
            end

            %% Show Info in text area

            SelectedEventIndicie = [];
            for neventchannel = 1:length(app.EventChannelforStimulationDropDown.Items)
                if strcmp(app.EventChannelforStimulationDropDown.Value,app.Mainapp.Data.Info.EventChannelNames{neventchannel})
                    SelectedEventIndicie = neventchannel;
                end
            end

            Texttoshow = ["Select the event channel you want to delete individual trigger for. 'Trigger to Plot' dropdown menu is only for plotting purposes! To actually reject trigger, the Trigger To Reject field is used. Rejection applies to all channel!"];
            Texttoshow = [Texttoshow;"";"Selected event channel: ";"";app.EventChannelforStimulationDropDown.Value];
            Texttoshow = [Texttoshow;strcat("Number of triggers in that channel: ",num2str(length(app.Mainapp.Data.Events{SelectedEventIndicie})))];

            app.InformationTextArea.Value = Texttoshow;

            %% Extract and plot stim artefacts
            if ~strcmp(app.EventstoPlotDropDown.Value,"Mean over all Trigger")
                EventToPlot = str2double(app.EventstoPlotDropDown.Value);
            else
                EventToPlot = 1:size(app.Mainapp.Data.EventRelatedData,2);
            end

            Organize_Plot_Trigger_Indices(app.Mainapp.Data,app.UIAxes,EventToPlot,app.Mainapp.Data.Info.EventRelatedTime,app.Mainapp.ActiveChannel,app.EventstoPlotDropDown.Value,app.Slider.Value,app.EventChannelforStimulationDropDown.Value,app.TimearoundEvent,app.DataToExtractFromDropDown.Value,"Raw Event Related Data",app.ColorMap)

        end

        % Callback function
        function ArtefactRejectionusingEventsButtonPushed(app, event)


        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            try
                app.Mainapp.EventIndiceRejectionWindow = [];
    
                if ~isempty(app.Mainapp.ProbeViewWindowHandle) % delete option from probe view 
                    for i = 1:length(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                        if strcmp(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Delete Event Trigger Window')
                            app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items(i) = [];
                            break;
                        end
                    end
                else % if no probe view available
     
                end
            end
            delete(app);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            
            %% Extract and plot stim artefacts
            if ~strcmp(app.EventstoPlotDropDown.Value,"Mean over all Trigger")
                EventToPlot = str2double(app.EventstoPlotDropDown.Value);
            else
                EventToPlot = 1:length(app.Mainapp.Data.Events{1});
            end

            Organize_Plot_Trigger_Indices(app.Mainapp.Data,app.UIAxes,EventToPlot,app.Mainapp.Data.Info.EventRelatedTime,app.Mainapp.ActiveChannel,app.EventstoPlotDropDown.Value,app.Slider.Value,app.EventChannelforStimulationDropDown.Value,app.TimearoundEvent,app.DataToExtractFromDropDown.Value,"Raw Event Related Data",app.ColorMap)
            
        end

        % Value changed function: EventstoPlotDropDown
        function EventstoPlotDropDownValueChanged(app, event)
            
            %% Extract and plot stim artefacts
            if ~strcmp(app.EventstoPlotDropDown.Value,"Mean over all Trigger")
                EventIndice = [];
                for i = 1:length(app.Mainapp.Data.Info.EventChannelNames)
                    if strcmp(app.Mainapp.Data.Info.EventChannelNames(i),app.EventChannelforStimulationDropDown.Value)
                        EventIndice = i;
                    end
                end

                Texttoshow = ["Select the event channel you want to delete individual trigger for. 'Trigger to Plot' dropdown menu is only for plotting purposes! To actually reject trigger, the Trigger To Reject field is used. Rejection applies to all channel!"];
                Texttoshow = [Texttoshow;"";"Selected event channel: ";"";app.EventChannelforStimulationDropDown.Value];
                Texttoshow = [Texttoshow;strcat("Number of triggers in that channel: ",num2str(length(app.Mainapp.Data.Events{EventIndice})))];
                app.InformationTextArea.Value = Texttoshow;
                Currenttime = app.Mainapp.Data.Events{EventIndice}(str2double(app.EventstoPlotDropDown.Value))/app.Mainapp.Data.Info.NativeSamplingRate;
                app.InformationTextArea.Value = [strcat("Selected Trigger Occurs at ",num2str(Currenttime)," seconds");app.InformationTextArea.Value];

                EventToPlot = str2double(app.EventstoPlotDropDown.Value);
            else
                EventIndice = [];
                for i = 1:length(app.Mainapp.Data.Info.EventChannelNames)
                    if strcmp(app.Mainapp.Data.Info.EventChannelNames(i),app.EventChannelforStimulationDropDown.Value)
                        EventIndice = i;
                    end
                end
                EventToPlot = 1:length(app.Mainapp.Data.Events{EventIndice});
                Texttoshow = ["Select the event channel you want to delete individual trigger for. 'Trigger to Plot' dropdown menu is only for plotting purposes! To actually reject trigger, the Trigger To Reject field is used. Rejection applies to all channel!"];
                Texttoshow = [Texttoshow;"";"Selected event channel: ";"";app.EventChannelforStimulationDropDown.Value];
                Texttoshow = [Texttoshow;strcat("Number of triggers in that channel: ",num2str(length(app.Mainapp.Data.Events{EventIndice})))];
                app.InformationTextArea.Value = Texttoshow;
            end

            Organize_Plot_Trigger_Indices(app.Mainapp.Data,app.UIAxes,EventToPlot,app.Mainapp.Data.Info.EventRelatedTime,app.Mainapp.ActiveChannel,app.EventstoPlotDropDown.Value,app.Slider.Value,app.EventChannelforStimulationDropDown.Value,app.TimearoundEvent,app.DataToExtractFromDropDown.Value,"Raw Event Related Data",app.ColorMap)
            
        end

        % Value changed function: EventChannelforStimulationDropDown
        function EventChannelforStimulationDropDownValueChanged(app, event)
            %% -------------------- Extract Event Related Data --------------------
            [app.Mainapp.Data,~] = Event_Module_Extract_Event_Related_Data(app.Mainapp.Data,app.EventChannelforStimulationDropDown.Value,app.TimearoundEvent,app.DataToExtractFromDropDown.Value,"Raw Event Related Data");
            
            %% -------------------- Setup trigger --------------------
            app.EventstoPlotDropDown.Items = {};
            app.EventstoPlotDropDown.Items{1} = 'Mean over all Trigger';

            for i = 1:size(app.Mainapp.Data.EventRelatedData,2)
                app.EventstoPlotDropDown.Items{i+1} = num2str(i);
            end

            if ~strcmp(app.EventstoPlotDropDown.Value,"Mean over all Trigger")
                EventIndice = [];
                for i = 1:length(app.Mainapp.Data.Info.EventChannelNames)
                    if strcmp(app.Mainapp.Data.Info.EventChannelNames(i),app.EventChannelforStimulationDropDown.Value)
                        EventIndice = i;
                    end
                end

                Texttoshow = ["Select the event channel you want to delete individual trigger for. 'Trigger to Plot' dropdown menu is only for plotting purposes! To actually reject trigger, the Trigger To Reject field is used. Rejection applies to all channel!"];
                Texttoshow = [Texttoshow;"";"Selected event channel: ";"";app.EventChannelforStimulationDropDown.Value];
                Texttoshow = [Texttoshow;strcat("Number of triggers in that channel: ",num2str(length(app.Mainapp.Data.Events{EventIndice})))];
                app.InformationTextArea.Value = Texttoshow;
                Currenttime = app.Mainapp.Data.Events{EventIndice}(str2double(app.EventstoPlotDropDown.Value))/app.Mainapp.Data.Info.NativeSamplingRate;
                app.InformationTextArea.Value = [strcat("Selected Trigger Occurs at ",num2str(Currenttime)," seconds");app.InformationTextArea.Value];

                EventToPlot = str2double(app.EventstoPlotDropDown.Value);
            else
                EventIndice = [];
                for i = 1:length(app.Mainapp.Data.Info.EventChannelNames)
                    if strcmp(app.Mainapp.Data.Info.EventChannelNames(i),app.EventChannelforStimulationDropDown.Value)
                        EventIndice = i;
                    end
                end
                EventToPlot = 1:size(app.Mainapp.Data.EventRelatedData,2);
                Texttoshow = ["Select the event channel you want to delete individual trigger for. 'Trigger to Plot' dropdown menu is only for plotting purposes! To actually reject trigger, the Trigger To Reject field is used. Rejection applies to all channel!"];
                Texttoshow = [Texttoshow;"";"Selected event channel: ";"";app.EventChannelforStimulationDropDown.Value];
                Texttoshow = [Texttoshow;strcat("Number of triggers in that channel: ",num2str(length(app.Mainapp.Data.Events{EventIndice})))];
                app.InformationTextArea.Value = Texttoshow;
            end


            Organize_Plot_Trigger_Indices(app.Mainapp.Data,app.UIAxes,EventToPlot,app.Mainapp.Data.Info.EventRelatedTime,app.Mainapp.ActiveChannel,app.EventstoPlotDropDown.Value,app.Slider.Value,app.EventChannelforStimulationDropDown.Value,app.TimearoundEvent,app.DataToExtractFromDropDown.Value,"Raw Event Related Data",app.ColorMap)
            

        end

        % Button pushed function: DeleteSelectedEventButton
        function DeleteSelectedEventButtonPushed(app, event)
            if isempty(app.TriggerToRejectMatlabExpressionsEditField.Value)
                msgbox("Please first specify trigger to reject!")
                return;
            end

            if isfield(app.Mainapp.Data.Info,'EventRelatedPreprocessing')
                msgbox("Event related preprocessing found which has to be deleted!")
                [app.Mainapp.Data,~] = Organize_Delete_Dataset_Components(app.Mainapp.Data,"PreprocessedEventRelatedData");
                [app.Mainapp.Data,~] = Organize_Delete_Dataset_Components(app.Mainapp.Data,"EventRelatedData");
            end

            EventIndice = [];
            for i = 1:length(app.Mainapp.Data.Info.EventChannelNames)
                if strcmp(app.Mainapp.Data.Info.EventChannelNames(i),app.EventChannelforStimulationDropDown.Value)
                    EventIndice = i;
                end
            end
            
            try
                triggertoreject = eval(app.TriggerToRejectMatlabExpressionsEditField.Value);
                if max(triggertoreject) > length(app.Mainapp.Data.Events{EventIndice}) || min(triggertoreject) < 1
                    if max(triggertoreject) > length(app.Mainapp.Data.Events{EventIndice})
                        [~,c] = max(triggertoreject);
                    end
                    if min(triggertoreject) < 1
                        [~,c] = min(triggertoreject);
                    end

                    msgbox(strcat("Event Indice ",num2str(triggertoreject(c))," exceeds limits of trigger available! Returning"));
                    return;
                end
            catch
                msgbox("Format error in trial rejection field. Please only use Matlab expressions.");
                return;
            end
            
            if length(triggertoreject) == length(app.Mainapp.Data.Events{EventIndice})
                if length(app.Mainapp.Data.Events)>1
                    app.Mainapp.Data.Events(EventIndice) = [];
                    app.Mainapp.Data.Info.EventChannelNames(EventIndice) = [];
                    msgbox(strcat("All trigger indices for channel ",app.EventChannelforStimulationDropDown.Value," deleted!"));
                else
                    [app.Mainapp.Data,~] = Organize_Delete_Dataset_Components(app.Mainapp.Data,"Events");
                    msgbox(strcat("All trigger indices for channel ",app.EventChannelforStimulationDropDown.Value," deleted! Since this is the only event channel available right now, event data component is deleted completly!"));
                end
            else
                app.Mainapp.Data.Events{EventIndice}(triggertoreject) = [];
                msgbox(strcat("Trigger indices ",num2str(triggertoreject)," for channel ",app.EventChannelforStimulationDropDown.Value," succesfully deleted!"))
            end
            
            %% Show new Info Header entries in main window
            
            Utility_Show_Info_Loaded_Data(app.Mainapp);
            
            [app.Mainapp] = Organize_Set_MainWindow_Dropdown(app.Mainapp,app.Mainapp.Data);

            KeepSpikes = 0;
            KeepEvents = 0;
            if isfield(app.Mainapp.Data,'Spikes') && strcmp(app.Mainapp.Plotspikes,"Spikes")
                KeepSpikes = 1;
            end
            if isfield(app.Mainapp.Data,'Events') && strcmp(app.Mainapp.PlotEvents,"Events")
                KeepEvents = 1;
            end

            Organize_Reset_Main_Plot(app.Mainapp,0,KeepEvents,KeepSpikes,0,app.Mainapp.CurrentTimePoints);
            
            app.Mainapp = Utility_Change_Light_Dark_Mode(app.Mainapp,"MainWindow");

            Organize_Delete_All_Open_Windows(app.Mainapp,0);

            delete(app);
        end

        % Menu selected function: ChangeChannelSpacingLimitsMenu
        function ChangeChannelSpacingLimitsMenuSelected(app, event)
            Channel_Spacing_Selection(app);
        end

        % Close request function: DeleteTriggerWindowUIFigure
        function DeleteTriggerWindowUIFigureCloseRequest(app, event)
            try
                app.Mainapp.EventIndiceRejectionWindow = [];
            
                if ~isempty(app.Mainapp.ProbeViewWindowHandle) % delete option from probe view 
                    for i = 1:length(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                        if strcmp(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Delete Event Trigger Window')
                            app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items(i) = [];
                            break;
                        end
                    end
                else % if no probe view available
     
                end
            end

            delete(app);
        end

        % Value changed function: DataToExtractFromDropDown
        function DataToExtractFromDropDownValueChanged(app, event)

            %% Show Info in text area

            SelectedEventIndicie = [];
            for neventchannel = 1:length(app.EventChannelforStimulationDropDown.Items)
                if strcmp(app.EventChannelforStimulationDropDown.Value,app.Mainapp.Data.Info.EventChannelNames{neventchannel})
                    SelectedEventIndicie = neventchannel;
                end
            end

            Texttoshow = ["Select the event channel you want to delete individual trigger for. 'Trigger to Plot' dropdown menu is only for plotting purposes! To actually reject trigger, the Trigger To Reject field is used. Rejection applies to all channel!"];
            Texttoshow = [Texttoshow;"";"Selected event channel: ";"";app.EventChannelforStimulationDropDown.Value];
            Texttoshow = [Texttoshow;strcat("Number of triggers in that channel: ",num2str(length(app.Mainapp.Data.Events{SelectedEventIndicie})))];

            app.InformationTextArea.Value = Texttoshow;

            %% Extract and plot stim artefacts
            if ~strcmp(app.EventstoPlotDropDown.Value,"Mean over all Trigger")
                EventToPlot = str2double(app.EventstoPlotDropDown.Value);
            else
                EventToPlot = 1:length(app.Mainapp.Data.Events{1});
            end

            Organize_Plot_Trigger_Indices(app.Mainapp.Data,app.UIAxes,EventToPlot,app.Mainapp.Data.Info.EventRelatedTime,app.Mainapp.ActiveChannel,app.EventstoPlotDropDown.Value,app.Slider.Value,app.EventChannelforStimulationDropDown.Value,app.TimearoundEvent,app.DataToExtractFromDropDown.Value,"Raw Event Related Data",app.ColorMap)

        end

        % Value changed function: TriggerToRejectMatlabExpressionsEditField
        function TriggerToRejectMatlabExpressionsEditFieldValueChanged(app, event)
            try
                selectedTrials = eval(app.TriggerToRejectMatlabExpressionsEditField.Value);
                if sum(selectedTrials > size(app.Mainapp.Data.EventRelatedData,2))>0 || sum(selectedTrials < 0)>0
                    msgbox("Error: One or more of the selected trigger exceed the maximum number of trigger for the currently selected event channel!");
                    app.TriggerToRejectMatlabExpressionsEditField.Value = event.PreviousValue;
                end
            catch
                msgbox("Formating error: Could not convert trial seelction to a double vector. Please use valiued Matlab expressions like 1:10 or [1,4,5,6]");
                app.TriggerToRejectMatlabExpressionsEditField.Value = event.PreviousValue;
            end
        end

        % Menu selected function: ChangeTimeWindowAroundTriggerMenu
        function ChangeTimeWindowAroundTriggerMenuSelected(app, event)
            Figure_Property_Change_Window([],"Time Range [from,to (both positive!)]",app.Mainapp,"DeleteIndiceChangeTimwWindow")

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.DeleteTriggerWindowUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {727, 727};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DeleteTriggerWindowUIFigure = uifigure('Visible', 'off');
            app.DeleteTriggerWindowUIFigure.Name = 'Delete Trigger Window';
            app.DeleteTriggerWindowUIFigure.Icon = 'Logo.png';
            app.DeleteTriggerWindowUIFigure.CloseRequestFcn = createCallbackFcn(app, @DeleteTriggerWindowUIFigureCloseRequest, true);
            app.DeleteTriggerWindowUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.ChangeTimeWindowAroundTriggerMenu = uimenu(app.DeleteTriggerWindowUIFigure);
            app.ChangeTimeWindowAroundTriggerMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeTimeWindowAroundTriggerMenuSelected, true);
            app.ChangeTimeWindowAroundTriggerMenu.Text = '   Change Time Window Around Trigger   ';
            app.GridLayout = uigridlayout(app.DeleteTriggerWindowUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.YTickLabel = '';
            app.DataLineSpacingSliderLabel = uilabel(app.RightPanel);
            app.DataLineSpacingSliderLabel.Text = {'Data Line'; 'Spacing'};
            app.Slider = uislider(app.RightPanel);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.RejectionOptionsPanel = uipanel(app.RightPanel);
            app.RejectionOptionsPanel.Title = 'Rejection Options';
            app.EventChannelofStimulationLabel = uilabel(app.RejectionOptionsPanel);
            app.EventChannelofStimulationLabel.Text = 'Event Channel of  Stimulation';
            app.EventChannelforStimulationDropDown = uidropdown(app.RejectionOptionsPanel);
            app.EventChannelforStimulationDropDown.Items = {''};
            app.EventChannelforStimulationDropDown.ValueChangedFcn = createCallbackFcn(app, @EventChannelforStimulationDropDownValueChanged, true);
            app.EventChannelforStimulationDropDown.Value = '';
            app.EventstoPlotLabel = uilabel(app.RejectionOptionsPanel);
            app.EventstoPlotLabel.Text = 'Trigger to Plot';
            app.EventstoPlotDropDown = uidropdown(app.RejectionOptionsPanel);
            app.EventstoPlotDropDown.Items = {'Mean over all Events'};
            app.EventstoPlotDropDown.ValueChangedFcn = createCallbackFcn(app, @EventstoPlotDropDownValueChanged, true);
            app.EventstoPlotDropDown.Value = 'Mean over all Events';
            app.TriggerToRejectMatlabExpressionsLabel = uilabel(app.RejectionOptionsPanel);
            app.TriggerToRejectMatlabExpressionsLabel.Text = 'Trigger To Reject (Matlab Expressions)';
            app.TriggerToRejectMatlabExpressionsEditField = uieditfield(app.RejectionOptionsPanel, 'text');
            app.TriggerToRejectMatlabExpressionsEditField.ValueChangedFcn = createCallbackFcn(app, @TriggerToRejectMatlabExpressionsEditFieldValueChanged, true);
            app.DataToExtractFromDropDownLabel = uilabel(app.RejectionOptionsPanel);
            app.DataToExtractFromDropDownLabel.Text = 'Data To Extract From';
            app.DataToExtractFromDropDown = uidropdown(app.RejectionOptionsPanel);
            app.DataToExtractFromDropDown.Items = {};
            app.DataToExtractFromDropDown.ValueChangedFcn = createCallbackFcn(app, @DataToExtractFromDropDownValueChanged, true);
            app.DataToExtractFromDropDown.Value = {};
            app.InformationTextAreaLabel = uilabel(app.RightPanel);
            app.InformationTextAreaLabel.Text = 'Information';
            app.InformationTextArea = uitextarea(app.RightPanel);
            app.CloseButton = uibutton(app.RightPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.RejectionOptionsLabel = uilabel(app.RightPanel);
            app.RejectionOptionsLabel.Text = 'Rejection Options';
            app.DeleteSelectedEventButton = uibutton(app.RightPanel, 'push');
            app.DeleteSelectedEventButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteSelectedEventButtonPushed, true);
            app.DeleteSelectedEventButton.Text = 'Delete Selected Trigger';
            app.ContextMenu = uicontextmenu(app.DeleteTriggerWindowUIFigure);
            app.ChangeChannelSpacingLimitsMenu = uimenu(app.ContextMenu);
            app.ChangeChannelSpacingLimitsMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeChannelSpacingLimitsMenuSelected, true);
            app.ChangeChannelSpacingLimitsMenu.Text = 'Change Channel Spacing Limits';
            % Assign app.ContextMenu
            app.Slider.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.DeleteTriggerWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Event_Indice_Rejection_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.DeleteTriggerWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.DeleteTriggerWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DeleteTriggerWindowUIFigure)
        end
    end
end