classdef BinSizeChangeWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BinSizeChangeUIFigure  matlab.ui.Figure
        EnteraValueLabel       matlab.ui.control.Label
        EditField              matlab.ui.control.EditField
    end

    
    properties (Access = public)
        SpikeWindow = []; % Description
        Type = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, SpikeWindow, Type)
            movegui(app.BinSizeChangeUIFigure, 'center');

            app.SpikeWindow = SpikeWindow;
            app.Type = Type;
            
            if strcmp(Type,"TimeBins")
                app.EnteraValueLabel.Text = "Change Time Bins [s]";
            elseif strcmp(Type,"DepthBins")
                app.EnteraValueLabel.Text = "Change Depth Bins [µm]";
            end           
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            
            if strcmp(app.Type,"TimeBins")
                app.SpikeWindow.SpikeBinSettings.time_bin_size = str2double(value); % Depth bin size
                app.SpikeWindow.SpikeBinSettings.time_edges = 0:app.SpikeWindow.SpikeBinSettings.time_bin_size:app.SpikeWindow.TimearoundEvent(2)+app.SpikeWindow.TimearoundEvent(1);
            elseif strcmp(app.Type,"DepthBins")
                app.SpikeWindow.SpikeBinSettings.depth_bin_size = str2double(value); % app.GeneralSettings.Time bin size in seconds
                app.SpikeWindow.SpikeBinSettings.depth_edges = min(app.SpikeWindow.Mainapp.Data.KilosortData.ChannelPosition(:,2)):app.SpikeWindow.SpikeBinSettings.depth_bin_size:max(app.SpikeWindow.Mainapp.Data.KilosortData.ChannelPosition(:,2));
            end      

            if strcmp(app.SpikeWindow.AnalysisTypeDropDown.Value,"Spike Point Plot")
               
            elseif strcmp(app.SpikeWindow.AnalysisTypeDropDown.Value,"Spike Rate Heatmap")
                Continous_Module_SpikeRate_over_Depth(app.SpikeWindow.GeneralSettings.SpikeTimes,app.SpikeWindow.GeneralSettings.SpikePositions,app.SpikeWindow.UIAxes,app.SpikeWindow.Mainapp.Data.Info.NativeSamplingRate,app.SpikeWindow.SpikeBinSettings.time_bin_size,app.SpikeWindow.SpikeBinSettings.depth_edges,app.SpikeWindow.SpikeBinSettings.time_edges,length(app.SpikeWindow.EventRange),app.SpikeWindow.TimearoundEvent(1),app.SpikeWindow.BaselineNormalizeCheckBox.Value,app.SpikeWindow.SpikeBinSettings.NormWindow,app.SpikeWindow.ClustertoshowDropDown.Value,app.SpikeWindow.GeneralSettings.SpikeCluster,app.SpikeWindow.rgbMatrix)
            end  

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BinSizeChangeUIFigure = uifigure('Visible', 'off');
            app.BinSizeChangeUIFigure.Name = 'Bin Size Change';
            app.BinSizeChangeUIFigure.Icon = 'Logo.png';
            app.EditField = uieditfield(app.BinSizeChangeUIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EnteraValueLabel = uilabel(app.BinSizeChangeUIFigure);
            app.EnteraValueLabel.Text = 'Enter a Value';
            % Show the figure after all components are created
            app.BinSizeChangeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BinSizeChangeWindow(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.BinSizeChangeUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.BinSizeChangeUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BinSizeChangeUIFigure)
        end
    end
end