classdef Export_Dataset_Components_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ManageDatasetComponentsWindowUIFigure  matlab.ui.Figure
        CloseButton                   matlab.ui.control.Button
        NeuromodToolboxLabel          matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        ExportDatasetComponentsWindowLabel  matlab.ui.control.Label
        TextArea                      matlab.ui.control.TextArea
        Panel                         matlab.ui.container.Panel
        ExportFormatDropDown          matlab.ui.control.DropDown
        ExportFormatDropDownLabel     matlab.ui.control.Label
        ExportDatasetComponentButton  matlab.ui.control.Button
        DropDown                      matlab.ui.control.DropDown
    end

    
    properties (Access = public)
        Mainapp = []; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            movegui(app.ManageDatasetComponentsWindowUIFigure, 'center');

            app.Mainapp = Mainapp;

            %% Populate fields of this GUI with Dataset components
            
            % Get the field names of the structure
            fieldNames = fieldnames(app.Mainapp.Data);
            
            % Convert the field names to a single string
            fieldNamesString = strjoin(fieldNames, ', ');
            
            texttoshow = {};
            elements = 1;
            for i = 1:length(fieldNames)
                if strcmp(fieldNames{i},'KilosortData')

                else
                    texttoshow{elements} = fieldNames{i};
                    elements = elements+1;
                end
            end

            % Display the field names in the Text Area
            app.TextArea.Value = texttoshow;

            texttoshow = {};
            elements = 1;

            for i = 1:length(fieldNames)
                if strcmp(fieldNames{i},'Time')
                    fieldNames{i} = [];
                
                elseif strcmp(fieldNames{i},'Info')
                    fieldNames{i} = [];
                elseif strcmp(fieldNames{i},'Spikes')
                    texttoshow{elements} = 'Spikes';
                    elements = elements+1;
                else
                    texttoshow{elements} = fieldNames{i};
                    elements = elements+1;
                end
            end

            app.DropDown.Items = texttoshow;
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: ExportDatasetComponentButton
        function ExportDatasetComponentButtonPushed(app, event)
            
            [app.Mainapp.Data,Error] = Organize_Delete_Dataset_Components(app.Mainapp.Data,app.DropDown.Value);

            if Error == 0
                if strcmp(app.DropDown.Value,"Spikes")
                    app.Mainapp.UIAxes.NextPlot = "replace"; 
                    plot(app.Mainapp.UIAxes,0,0);
                    app.Mainapp.UIAxes_2.NextPlot = "replace"; 
                    plot(app.Mainapp.UIAxes_2,0,0);
                    app.Mainapp.Plotspikes = "No";
    
                    app.Mainapp.SpikeDataCheckBox_2.Value = 0;
                    app.Mainapp.SpikeDataCheckBox_2.Enable = "off";
                end
        
                if strcmp(app.DropDown.Value,"Events")
                    app.Mainapp.PlotEvents = "No";
                    app.Mainapp.UIAxes.NextPlot = "replace"; 
                    plot(app.Mainapp.UIAxes,0,0);
                    app.Mainapp.UIAxes_2.NextPlot = "replace"; 
                    plot(app.Mainapp.UIAxes_2,0,0);
                    app.Mainapp.PlotEvents = "no";
    
                    app.Mainapp.EventDataCheckBox.Value = 0;
                    app.Mainapp.EventDataCheckBox.Enable = "off";
                end
    
                if strcmp(app.DropDown.Value,"Preprocessed")
                    app.Mainapp.PreprocDataPlotCheckBox.Value = 0;
                    app.Mainapp.PreprocDataPlotCheckBox.Enable = "off";
                end
    
                if strcmp(app.DropDown.Value,"Raw")
                    app.Mainapp.RawDataPlotCheckBox.Value = 0;
                    app.Mainapp.RawDataPlotCheckBox.Enable = "off";
                end
    
                Utility_Show_Info_Loaded_Data(app.Mainapp);

                %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
    
    
                %% Update the fields of this GUI with the reamining dataset components
                % Get the field names of the structure
                fieldNames = fieldnames(app.Mainapp.Data);
                
                % Convert the field names to a single string
                fieldNamesString = strjoin(fieldNames, ', ');
                
                texttoshow = {};
                elements = 1;
                for i = 1:length(fieldNames)
                    if strcmp(fieldNames{i},'KilosortData')
                       
                    else
                        texttoshow{elements} = fieldNames{i};
                        elements = elements+1;
                    end
                end
    
                % Display the field names in the Text Area
                app.TextArea.Value = texttoshow;
    
                texttoshow = {};
                elements = 1;
    
                for i = 1:length(fieldNames)
                    if strcmp(fieldNames{i},'Time')
                        fieldNames{i} = [];
                    
                    elseif strcmp(fieldNames{i},'Info')
                        fieldNames{i} = [];
                    elseif strcmp(fieldNames{i},'Spikes')
                        texttoshow{elements} = 'Spikes';
                        elements = elements+1;
                    else
                        texttoshow{elements} = fieldNames{i};
                        elements = elements+1;
                    end
                end
    
                app.DropDown.Items = texttoshow;

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ManageDatasetComponentsWindowUIFigure = uifigure('Visible', 'off');
            app.ManageDatasetComponentsWindowUIFigure.Name = 'Manage Dataset Components Window';
            app.ManageDatasetComponentsWindowUIFigure.Icon = 'Logo.png';
            app.Panel = uipanel(app.ManageDatasetComponentsWindowUIFigure);
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.Items = {};
            app.DropDown.Value = {};
            app.ExportDatasetComponentButton = uibutton(app.Panel, 'push');
            app.ExportDatasetComponentButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDatasetComponentButtonPushed, true);
            app.ExportDatasetComponentButton.Text = {'Export Dataset'; 'Component'};
            app.ExportFormatDropDownLabel = uilabel(app.Panel);
            app.ExportFormatDropDownLabel.Text = 'Export Format';
            app.ExportFormatDropDown = uidropdown(app.Panel);
            app.ExportFormatDropDown.Items = {'.mat', '.txt', '.csv'};
            app.ExportFormatDropDown.Value = '.mat';
            app.TextArea = uitextarea(app.ManageDatasetComponentsWindowUIFigure);
            app.ExportDatasetComponentsWindowLabel = uilabel(app.ManageDatasetComponentsWindowUIFigure);
            app.ExportDatasetComponentsWindowLabel.Text = 'ExportDataset Components Window';
            app.Image = uiimage(app.ManageDatasetComponentsWindowUIFigure);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodToolboxLabel = uilabel(app.ManageDatasetComponentsWindowUIFigure);
            app.NeuromodToolboxLabel.Text = 'Neuromod Toolbox';
            app.CloseButton = uibutton(app.ManageDatasetComponentsWindowUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.ManageDatasetComponentsWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Export_Dataset_Components_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ManageDatasetComponentsWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ManageDatasetComponentsWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ManageDatasetComponentsWindowUIFigure)
        end
    end
end