classdef BinSizeChangeWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BinSizeChangeUIFigure     matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        RightPanel                matlab.ui.container.Panel
        TextArea_3                matlab.ui.control.TextArea
        TextArea_2                matlab.ui.control.TextArea
        TextArea                  matlab.ui.control.TextArea
        Image                     matlab.ui.control.Image
        NeuromodLabel             matlab.ui.control.Label
        ChangeBinSizeWindowLabel  matlab.ui.control.Label
        NewBinSizeLabel           matlab.ui.control.Label
        EditField                 matlab.ui.control.EditField
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        SpikeWindow = []; % Description
        Type = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, SpikeWindow, Type)
            movegui(app.BinSizeChangeUIFigure, 'center');

            app.SpikeWindow = SpikeWindow;
            app.Type = Type;
            
            if strcmp(Type,"TimeBins")
                app.NewBinSizeLabel.Text = "Change Nr Time Bins [s]";
            elseif strcmp(Type,"DepthBins")
                app.NewBinSizeLabel.Text = "Change Nr Depth Bins [µm]";
            end           
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            [CorrectedValue] = Utility_SimpleCheckInputs(app.EditField.Value,"One",'10',0,0);
            
            if ~strcmp(CorrectedValue,app.EditField.Value)
                msgbox("Error: Wrong format. Please enter as single number")
                return;
            end

            value = app.EditField.Value;
            
            if strcmp(app.Type,"TimeBins")
                app.SpikeWindow.SpikeBinSettings.time_bin_size = str2double(value); % Depth bin size
                app.SpikeWindow.SpikeBinSettings.time_edges = linspace(app.SpikeWindow.TimearoundEvent(1),app.SpikeWindow.TimearoundEvent(2),app.SpikeWindow.SpikeBinSettings.time_bin_size);
            elseif strcmp(app.Type,"DepthBins")
                commaindicie = find(app.SpikeWindow.ChannelSelectionforPlottingEditField.Value == ',');
                Tempnumchannel(1) = str2double(app.SpikeWindow.ChannelSelectionforPlottingEditField.Value(1:commaindicie(1)-1));
                Tempnumchannel(2) = str2double(app.SpikeWindow.ChannelSelectionforPlottingEditField.Value(commaindicie(1)+1:end));
                numchannel = length(Tempnumchannel(1):Tempnumchannel(2));

                app.SpikeWindow.SpikeBinSettings.depth_bin_size = str2double(value); % app.GeneralSettings.Time bin size in seconds
                app.SpikeWindow.SpikeBinSettings.depth_edges = linspace(0,numchannel*app.SpikeWindow.Mainapp.Data.Info.ChannelSpacing,app.SpikeWindow.SpikeBinSettings.depth_bin_size);                
            end      

            if strcmp(app.SpikeWindow.AnalysisTypeDropDown.Value,"Spike Point Plot")
               
            elseif strcmp(app.SpikeWindow.AnalysisTypeDropDown.Value,"Spike Rate Heatmap")
                app.SpikeWindow.UIAxes.NextPlot = "replace"; 
                plot(app.SpikeWindow.UIAxes,0,0);
                app.SpikeWindow.UIAxes.NextPlot = "add"; 
                set(app.SpikeWindow.UIAxes, 'YDir','reverse');
                set(app.SpikeWindow.UIAxes,'xticklabel',{[]});

                [~,app.SpikeWindow.ChannelSelectionforPlottingEditField.Value,app.SpikeWindow.EventRangeEditField.Value,app.SpikeWindow.SpikeRateNumBinsEditField.Value,app.SpikeWindow.Mainapp.CurrentPlotData] = Events_Spikes_Manage_Analysis_Plots(app.SpikeWindow.Mainapp.Data,app.SpikeWindow.EventRangeEditField.Value,app.SpikeWindow.UIAxes,app.SpikeWindow.AnalysisTypeDropDown.Value,app.SpikeWindow.SpikeRateNumBinsEditField.Value,app.SpikeWindow.TextArea,app.SpikeWindow.rgbMatrix,app.SpikeWindow.numCluster,app.SpikeWindow.ClustertoshowDropDown.Value,app.SpikeWindow.ChannelSelectionforPlottingEditField.Value,app.SpikeWindow.BaselineWindowStartStopinsEditField,app.SpikeWindow.BaselineNormalizeCheckBox.Value,app.SpikeWindow.TimeWindowSpiketriggredLFPEditField.Value,app.SpikeWindow.UIAxes_3,app.SpikeWindow.UIAxes_5,app.SpikeWindow.TwoORThreeD,app.SpikeWindow.Mainapp.CurrentPlotData,app.SpikeWindow.SpikeBinSettings,app.SpikeWindow.Mainapp.PlotAppearance,app.SpikeWindow.Mainapp.ActiveChannel);
            end  

            delete(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.BinSizeChangeUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {152, 152};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BinSizeChangeUIFigure = uifigure('Visible', 'off');
            app.BinSizeChangeUIFigure.Name = 'Bin Size Change';
            app.BinSizeChangeUIFigure.Icon = 'Logo.png';
            app.BinSizeChangeUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.BinSizeChangeUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.EditField = uieditfield(app.RightPanel, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.NewBinSizeLabel = uilabel(app.RightPanel);
            app.NewBinSizeLabel.Text = 'New Bin Size';
            app.ChangeBinSizeWindowLabel = uilabel(app.RightPanel);
            app.ChangeBinSizeWindowLabel.Text = 'Change Bin Size Window';
            app.NeuromodLabel = uilabel(app.RightPanel);
            app.NeuromodLabel.Text = 'Neuromod ';
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = 'Logo.png';
            app.TextArea = uitextarea(app.RightPanel);
            app.TextArea.Editable = 'off';
            app.TextArea.Enable = 'off';
            app.TextArea.Visible = 'off';
            app.TextArea_2 = uitextarea(app.RightPanel);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Enable = 'off';
            app.TextArea_2.Visible = 'off';
            app.TextArea_3 = uitextarea(app.RightPanel);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Enable = 'off';
            app.TextArea_3.Visible = 'off';
            % Show the figure after all components are created
            app.BinSizeChangeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BinSizeChangeWindow(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.BinSizeChangeUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.BinSizeChangeUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BinSizeChangeUIFigure)
        end
    end
end