classdef Change_Plot_Speed < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ChannelSpacingSelectionUIFigure  matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        RightPanel            matlab.ui.container.Panel
        TextArea_4            matlab.ui.control.TextArea
        TextArea_3            matlab.ui.control.TextArea
        TextArea_2            matlab.ui.control.TextArea
        TextArea              matlab.ui.control.TextArea
        NeuromodLabel         matlab.ui.control.Label
        Image                 matlab.ui.control.Image
        ChangePlotSpeedLabel  matlab.ui.control.Label
        TimetoJumpsPressEntertoConfirmEditField  matlab.ui.control.EditField
        TimetoJumpsPressEntertoConfirmEditFieldLabel  matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Mainapp = []; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            movegui(app.ChannelSpacingSelectionUIFigure, 'center');

            app.Mainapp = Mainapp;

            app.TimetoJumpsPressEntertoConfirmEditField.Value = num2str(app.Mainapp.MovieTimeToJump);
        end

        % Value changed function: TimetoJumpsPressEntertoConfirmEditField
        function TimetoJumpsPressEntertoConfirmEditFieldValueChanged(app, event)
            [CorrectedValue] = Utility_SimpleCheckInputs(app.TimetoJumpsPressEntertoConfirmEditField.Value,"One",'0.02',0,0);
            
            if ~strcmp(CorrectedValue,app.TimetoJumpsPressEntertoConfirmEditField.Value)
                msgbox("Error: Wrong format. Please enter a single number")
                return;
            end

            app.Mainapp.MovieTimeToJump = str2double(app.TimetoJumpsPressEntertoConfirmEditField.Value);
            
            msgbox(strcat("Time to jump changed to ",num2str(app.Mainapp.MovieTimeToJump)))
            delete(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ChannelSpacingSelectionUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {251, 251};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ChannelSpacingSelectionUIFigure = uifigure('Visible', 'off');
            app.ChannelSpacingSelectionUIFigure.Name = 'Channel Spacing Selection';
            app.ChannelSpacingSelectionUIFigure.Icon = 'Logo.png';
            app.ChannelSpacingSelectionUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.ChannelSpacingSelectionUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.TimetoJumpsPressEntertoConfirmEditFieldLabel = uilabel(app.RightPanel);
            app.TimetoJumpsPressEntertoConfirmEditFieldLabel.Text = {'Time to Jump [s] '; 'Press Enter to Confirm'};
            app.TimetoJumpsPressEntertoConfirmEditField = uieditfield(app.RightPanel, 'text');
            app.TimetoJumpsPressEntertoConfirmEditField.ValueChangedFcn = createCallbackFcn(app, @TimetoJumpsPressEntertoConfirmEditFieldValueChanged, true);
            app.ChangePlotSpeedLabel = uilabel(app.RightPanel);
            app.ChangePlotSpeedLabel.Text = 'Change Plot Speed';
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodLabel = uilabel(app.RightPanel);
            app.NeuromodLabel.Text = 'Neuromod';
            app.TextArea = uitextarea(app.RightPanel);
            app.TextArea.Editable = 'off';
            app.TextArea.Enable = 'off';
            app.TextArea.Visible = 'off';
            app.TextArea_2 = uitextarea(app.RightPanel);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Enable = 'off';
            app.TextArea_2.Visible = 'off';
            app.TextArea_3 = uitextarea(app.RightPanel);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Enable = 'off';
            app.TextArea_3.Visible = 'off';
            app.TextArea_4 = uitextarea(app.RightPanel);
            app.TextArea_4.Editable = 'off';
            app.TextArea_4.Value = {'The update speed of the main data plot is not faster than 40 fps, but you can change the amount of time jumped per frame. Default is 0.02 '};
            % Show the figure after all components are created
            app.ChannelSpacingSelectionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Change_Plot_Speed(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ChannelSpacingSelectionUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ChannelSpacingSelectionUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ChannelSpacingSelectionUIFigure)
        end
    end
end