classdef Channel_Spacing_Selection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ChannelSpacingSelectionUIFigure  matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        RightPanel                    matlab.ui.container.Panel
        TextArea_3                    matlab.ui.control.TextArea
        TextArea_2                    matlab.ui.control.TextArea
        TextArea                      matlab.ui.control.TextArea
        NeuromodLabel                 matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        ChangeDataLineSpacingLabel    matlab.ui.control.Label
        SpacingLimitsminmaxEditField  matlab.ui.control.EditField
        NewSpacingLimitsminmaxEditFieldLabel  matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Mainapp = []; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            movegui(app.ChannelSpacingSelectionUIFigure, 'center');

            app.Mainapp = Mainapp;
        end

        % Value changed function: SpacingLimitsminmaxEditField
        function SpacingLimitsminmaxEditFieldValueChanged(app, event)
            [CorrectedValue] = Utility_SimpleCheckInputs(app.SpacingLimitsminmaxEditField.Value,"Two",app.Mainapp.Slider.Limits,0,1);
            
            if ~strcmp(CorrectedValue,app.SpacingLimitsminmaxEditField.Value)
                msgbox("Error: Wrong format. Please enter as [lower limit, upper limit]")
                return;
            end

            value = app.SpacingLimitsminmaxEditField.Value;

            % Split the string using comma as delimiter
            splitStr = split(value, ',');
            
            % Convert the substrings to numbers
            Minslider = str2double(splitStr{1});
            Maxslider = str2double(splitStr{2});


            app.Mainapp.Slider.Limits = [Minslider,Maxslider];

            delete(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ChannelSpacingSelectionUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {147, 147};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ChannelSpacingSelectionUIFigure = uifigure('Visible', 'off');
            app.ChannelSpacingSelectionUIFigure.Name = 'Channel Spacing Selection';
            app.ChannelSpacingSelectionUIFigure.Icon = 'Logo.png';
            app.ChannelSpacingSelectionUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.ChannelSpacingSelectionUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.NewSpacingLimitsminmaxEditFieldLabel = uilabel(app.RightPanel);
            app.NewSpacingLimitsminmaxEditFieldLabel.Text = {'New Spacing Limits'; '[min,max]'};
            app.SpacingLimitsminmaxEditField = uieditfield(app.RightPanel, 'text');
            app.SpacingLimitsminmaxEditField.ValueChangedFcn = createCallbackFcn(app, @SpacingLimitsminmaxEditFieldValueChanged, true);
            app.ChangeDataLineSpacingLabel = uilabel(app.RightPanel);
            app.ChangeDataLineSpacingLabel.Text = 'Change Data Line Spacing';
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodLabel = uilabel(app.RightPanel);
            app.NeuromodLabel.Text = 'Neuromod';
            app.TextArea = uitextarea(app.RightPanel);
            app.TextArea.Editable = 'off';
            app.TextArea.Enable = 'off';
            app.TextArea.Visible = 'off';
            app.TextArea_2 = uitextarea(app.RightPanel);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Enable = 'off';
            app.TextArea_2.Visible = 'off';
            app.TextArea_3 = uitextarea(app.RightPanel);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Enable = 'off';
            app.TextArea_3.Visible = 'off';
            % Show the figure after all components are created
            app.ChannelSpacingSelectionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Channel_Spacing_Selection(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ChannelSpacingSelectionUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ChannelSpacingSelectionUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ChannelSpacingSelectionUIFigure)
        end
    end
end