classdef Figure_Property_Change_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Figure_Property_Change_WindowUIFigure  matlab.ui.Figure
        NeuromodToolboxLabel          matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        ChangeMaxTimeSpanWindowLabel  matlab.ui.control.Label
        EnteraValueLabel              matlab.ui.control.Label
        EditField                     matlab.ui.control.EditField
    end

    
    properties (Access = public)
        Figure = [] % Description
        Type = [] % Description+
        Mainapp = [];
        Plot = [];
        SelectedColor = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Figure, Type, Mainapp, Plot)
            movegui(app.Figure_Property_Change_WindowUIFigure, 'center');

            app.Figure = Figure;
            app.Type = Type;
            app.Mainapp = Mainapp;
            app.Plot = Plot;

            if strcmp(Type,'title')
                app.EnteraValueLabel.Text = 'Enter New Title';
            elseif strcmp(Type,'xlabel')
                app.EnteraValueLabel.Text = 'Enter New X Label';
            elseif strcmp(Type,'ylabel')
                app.EnteraValueLabel.Text = 'Enter New Y Label';
            elseif strcmp(Type,'xlim')
                app.EnteraValueLabel.Text = 'Enter New X limit [from,to]';
            elseif strcmp(Type,'ylim')
                app.EnteraValueLabel.Text = 'Enter New Y limit [from,to]';
            elseif strcmp(Type,'fontsize')
                app.EnteraValueLabel.Text = 'Enter New Fontsize';
            elseif strcmp(Type,'linewidth') 
                app.EnteraValueLabel.Text = 'Enter New LineWidth';
            elseif strcmp(Type,'backgroundcolor') 
                app.EnteraValueLabel.Text = 'Enter New Background Color [r,g,b]';
            end
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            %% Main Window Data
            % Labels 
            if strcmp(app.Plot,"MainWindowDataTitle")
                if app.Mainapp.RawDataPlotCheckBox.Value == 1
                    app.Mainapp.PlotAppearance.MainWindow.Data.Title.Raw = value;
                elseif app.Mainapp.PreprocDataPlotCheckBox.Value == 1
                    app.Mainapp.PlotAppearance.MainWindow.Data.Title.Preprocessed = value;
                end
            elseif strcmp(app.Plot,"MainWindowDataXlabel")
                app.Mainapp.PlotAppearance.MainWindow.Data.MainXLabel = value;
            elseif strcmp(app.Plot,"MainWindowDataYlabel")
                app.Mainapp.PlotAppearance.MainWindow.Data.MainYLabel = value;
            elseif strcmp(app.Plot,"MainWindowDataFontSize")
                app.Mainapp.PlotAppearance.MainWindow.Data.MainFontSize = str2double(value);
                app.Figure.FontSize = app.Mainapp.PlotAppearance.MainWindow.Data.MainFontSize;
            %Line Width
            elseif strcmp(app.Plot,"MainWindowDataLineWidthData")
                app.Mainapp.PlotAppearance.MainWindow.Data.LineWidth.MainData = str2double(value);
            elseif strcmp(app.Plot,"MainWindowDataLineWidthEvent")
                app.Mainapp.PlotAppearance.MainWindow.Data.LineWidth.MainEvents = str2double(value);
            elseif strcmp(app.Plot,"MainWindowDataLineWidthSpikes")
                app.Mainapp.PlotAppearance.MainWindow.Data.LineWidth.MainSpikes = str2double(value);
            % Colors
            elseif strcmp(app.Plot,"MainWindowDataColorSpikes")
                app.Mainapp.PlotAppearance.MainWindow.Data.Color.MainSpikes = str2double(value);
            elseif strcmp(app.Plot,"MainWindowDataColorEvents")
                app.Mainapp.PlotAppearance.MainWindow.Data.Color.MainEvents = str2double(value);
            elseif strcmp(app.Plot,"MainWindowDataColorBackGround")
                app.Mainapp.PlotAppearance.MainWindow.Data.Color.MainBackground = str2double(value);
                app.Figure.Color = app.Mainapp.PlotAppearance.MainWindow.Data.Color.MainBackground;
            %% Main Window Time
            elseif strcmp(app.Plot,"MainWindowTimeTitle")
                app.Mainapp.PlotAppearance.MainWindow.Data.TimeTitle = value;
            elseif strcmp(app.Plot,"MainWindowTimeFontSize")
                app.Mainapp.PlotAppearance.MainWindow.Data.TimeFontSize = str2double(value);
                app.Figure.FontSize = app.Mainapp.PlotAppearance.MainWindow.Data.TimeFontSize;
            elseif strcmp(app.Plot,"MainWindowTimeYlabel")
                app.Mainapp.PlotAppearance.MainWindow.Data.TimeYLabel = value;
            elseif strcmp(app.Plot,"MainWindowTimeXlabel")
                app.Mainapp.PlotAppearance.MainWindow.Data.TimeXLabel = value;
            %Line Width
            elseif strcmp(app.Plot,"MainWindowTimeLineWidthRectangle")
                app.Mainapp.PlotAppearance.MainWindow.Data.LineWidth.TimeRectangle = str2double(value);
            elseif strcmp(app.Plot,"MainWindowTimeLineWidthEvent")
                app.Mainapp.PlotAppearance.MainWindow.Data.LineWidth.TimeEvents = str2double(value);
            % Colors
            elseif strcmp(app.Plot,"MainWindowTimeColorRectangle")
                app.Mainapp.PlotAppearance.MainWindow.Data.Color.TimeRectangle = str2double(value);
            elseif strcmp(app.Plot,"MainWindowTimeColorEvents")
                app.Mainapp.PlotAppearance.MainWindow.Data.Color.TimeEvents = str2double(value);
            elseif strcmp(app.Plot,"MainWindowTimeBackGround")
                app.Mainapp.PlotAppearance.MainWindow.Data.Color.TimeBackground = str2double(value);
                app.Figure.Color = app.Mainapp.PlotAppearance.MainWindow.Data.Color.TimeBackground;
            end
            %% Cont. Power Spectrum

            if strcmp(app.Plot,"SpectrumFontSize")
                app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeFontSize = str2double(value);
            elseif strcmp(app.Plot,"SpectrumLineWidth")
                app.Mainapp.PlotAppearance.SpectrumWindow.Data.SpectrumLinwWidth = str2double(value);
            elseif strcmp(app.Plot,"SpectrumWindowDataXlabel")
                app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeXLabel = value;
            elseif strcmp(app.Plot,"SpectrumWindowDataYlabel")
                app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeYLabel = value;
            end
            
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Figure_Property_Change_WindowUIFigure = uifigure('Visible', 'off');
            app.Figure_Property_Change_WindowUIFigure.Name = 'Figure_Property_Change_Window';
            app.Figure_Property_Change_WindowUIFigure.Icon = 'Logo.png';
            app.EditField = uieditfield(app.Figure_Property_Change_WindowUIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EnteraValueLabel = uilabel(app.Figure_Property_Change_WindowUIFigure);
            app.EnteraValueLabel.Text = 'Enter a Value';
            app.ChangeMaxTimeSpanWindowLabel = uilabel(app.Figure_Property_Change_WindowUIFigure);
            app.ChangeMaxTimeSpanWindowLabel.Text = 'Change Max. Time Span Window';
            app.Image = uiimage(app.Figure_Property_Change_WindowUIFigure);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodToolboxLabel = uilabel(app.Figure_Property_Change_WindowUIFigure);
            app.NeuromodToolboxLabel.Text = 'Neuromod Toolbox';
            % Show the figure after all components are created
            app.Figure_Property_Change_WindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Figure_Property_Change_Window(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Figure_Property_Change_WindowUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Figure_Property_Change_WindowUIFigure)
        end
    end
end