classdef Manage_Dataset_Components < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ManageDatasetComponentsWindowUIFigure  matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        RightPanel                    matlab.ui.container.Panel
        CloseButton                   matlab.ui.control.Button
        NeuromodLabel                 matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        ManageDatasetComponentsWindowLabel  matlab.ui.control.Label
        TextArea                      matlab.ui.control.TextArea
        ManagementOptionsPanel        matlab.ui.container.Panel
        ExportFormatDropDownLabel_2   matlab.ui.control.Label
        ExportFormatDropDown          matlab.ui.control.DropDown
        ExportFormatDropDownLabel     matlab.ui.control.Label
        ExportDatasetComponentButton  matlab.ui.control.Button
        DeleteDatasetComponentButton  matlab.ui.control.Button
        DropDown                      matlab.ui.control.DropDown
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Mainapp = []; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            movegui(app.ManageDatasetComponentsWindowUIFigure, 'center');

            app.Mainapp = Mainapp;

            %% Populate fields of this GUI with Dataset components
            
            % Get the field names of the structure
            fieldNames = fieldnames(app.Mainapp.Data);
            
            % Convert the field names to a single string
            fieldNamesString = strjoin(fieldNames, ', ');
            
            texttoshow = {};
            elements = 0;
            for i = 1:length(fieldNames)
                if strcmp(fieldNames(i),"Raw") || strcmp(fieldNames(i),"Preprocessed")
                    elements = elements+1;
                    texttoshow{end+1} = strcat(num2str(elements),".: ",fieldNames{i}," Data");
                elseif strcmp(fieldNames(i),"Events")
                    elements = elements+1;
                    texttoshow{end+1} = strcat(num2str(elements),".: ",fieldNames{i}," Data; Cell array with Dimension: ",num2str(size(app.Mainapp.Data.Events)));
                    for nevents = 1:length(app.Mainapp.Data.Events)
                        texttoshow{end+1} = [strcat("Event Nr ", num2str(nevents)," contains ",num2str(length(app.Mainapp.Data.Events{nevents}))," Events.")];
                    end
                    elements = elements+nevents;
                elseif strcmp(fieldNames(i),"Spikes")
                    elements = elements+1;
                    SpikeFieldnames = fieldnames(app.Mainapp.Data.Spikes);
                    texttoshow{end+1} = strcat(num2str(elements),".: ",fieldNames{i}," Data; Structure with ",num2str(length(SpikeFieldnames))," Fields.");
                    for nspikefields = 1:length(SpikeFieldnames)
                        if strcmp(SpikeFieldnames{nspikefields},"SpikeTimes")
                            CurrentSpikeFieldname = SpikeFieldnames{nspikefields};
                            texttoshow{end+1} = strcat("Spike Field ", CurrentSpikeFieldname," contains ",num2str(size(app.Mainapp.Data.Spikes.(CurrentSpikeFieldname)))," Elements.");
                        end
                    end
                    elements = elements+1;
                elseif strcmp(fieldNames(i),"Info")
                    elements = elements+1;
                    InfoFieldnames = fieldnames(app.Mainapp.Data.Info);
                    texttoshow{end+1} = strcat(num2str(elements-1),".: ",fieldNames{i}," Data; Structure with ",num2str(length(InfoFieldnames))," Fields.");
                else
                    elements = elements+1;
                    CurrentFieldname = fieldNames{i};
                    texttoshow{end+1} = strcat(num2str(elements-1),".: ",fieldNames{i},"; Dimension: ",num2str(size(app.Mainapp.Data.(CurrentFieldname))));
                end
            end

            texttoshow = ["Export or delete dataset components. Raw and preprocessed data can only be exported as .mat or using the 'Save Dataset' function of the 'Manage Dataset_ module as .dat (recommended) or .mat file. Standard folder the export data to is 'GUI_Path/Analysis Results'. The following dataset components where found:","",texttoshow];
            % Display the field names in the Text Area
            app.TextArea.Value = texttoshow;

            texttoshow = {};
            elements = 1;

            for i = 1:length(fieldNames)
                if strcmp(fieldNames{i},'Spikes')
                    texttoshow{elements} = 'Spikes';
                    elements = elements+1;
                else
                    texttoshow{elements} = fieldNames{i};
                    elements = elements+1;
                end
            end

            app.DropDown.Items = texttoshow;

            value = app.DropDown.Value;

            % Export restrictions    
            if strcmp(value,"Raw") || strcmp(value,"Preprocessed")
                app.ExportFormatDropDown.Items = {};
                app.ExportFormatDropDown.Items{1} = '.mat';
            else
                app.ExportFormatDropDown.Items = {};
                app.ExportFormatDropDown.Items{1} = '.mat';
                app.ExportFormatDropDown.Items{2} = '.txt';
                app.ExportFormatDropDown.Items{3} = '.csv';
            end
            % Delete restrictions
            if strcmp(value,"Time") || strcmp(value,"Info") || strcmp(value,"TimeDownsampled") || strcmp(value,"Raw")
                app.DeleteDatasetComponentButton.Enable = "off";
            else
                app.DeleteDatasetComponentButton.Enable = "on";
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: DeleteDatasetComponentButton
        function DeleteDatasetComponentButtonPushed(app, event)
            
            [app.Mainapp.Data,Error] = Organize_Delete_Dataset_Components(app.Mainapp.Data,app.DropDown.Value);

            if Error == 0
                
                app.Mainapp.UIAxes.NextPlot = "replace"; 
                plot(app.Mainapp.UIAxes,0,0);
                app.Mainapp.UIAxes_2.NextPlot = "replace"; 
                plot(app.Mainapp.UIAxes_2,0,0);
                
                Placeholder = {};
                app.Mainapp.DropDown.Items = Placeholder;
                app.Mainapp.DropDown.Items{1} = 'Raw Data';

                app.Mainapp.DropDown_2.Items = Placeholder;
                app.Mainapp.DropDown_2.Items{1} = 'Non';

                app.Mainapp.DropDown.Items = Placeholder;
                app.Mainapp.DropDown.Items{1} = 'Raw Data';

                app.Mainapp.DropDown_2.Items = Placeholder;
                app.Mainapp.DropDown_2.Items{1} = 'Non';

                if isfield(app.Mainapp.Data,'Preprocessed')
                    if ~isempty(app.Mainapp.Data.Preprocessed)
                        app.Mainapp.DropDown.Items{2} = 'Preprocessed Data';
                        app.Mainapp.DropDown.Value = 'Raw Data';
                    else
                        if length(app.Mainapp.DropDown.Items) == 2
                            app.Mainapp.DropDown.Items(2) = [];
                            app.Mainapp.DropDown.Value = 'Raw Data';
                        else
                            app.Mainapp.DropDown.Value = 'Raw Data';
                        end
                    end
                else
                    if length(app.Mainapp.DropDown.Items) == 2
                        app.Mainapp.DropDown.Items(2) = [];
                        app.Mainapp.DropDown.Value = 'Raw Data';
                    else
                        app.Mainapp.DropDown.Value = 'Raw Data';
                    end
                end
            
                if ~isfield(app.Mainapp.Data,'Events')
                    app.Mainapp.DropDown_2.Items{1} = 'Non';
                    if isfield(app.Mainapp.Data,'Spikes')
                        app.Mainapp.DropDown_2.Items{2} = 'Spikes';
                    end
                    app.Mainapp.EventChannelDropDown.Items = Placeholder;
                    app.Mainapp.EventChannelDropDown.Enable = "off";
                else
                    app.Mainapp.DropDown_2.Items{1} = 'Non';
                    app.Mainapp.DropDown_2.Items{2} = 'Events';
                    if isfield(app.Mainapp.Data,'Spikes')
                        app.Mainapp.DropDown_2.Items{3} = 'Spikes';
                    end
                end
            
                if ~isfield(app.Mainapp.Data,'Spikes')
                    app.Mainapp.DropDown_2.Items{1} = 'Non';
                    if isfield(app.Mainapp.Data,'Events')
                        app.Mainapp.DropDown_2.Items{2} = 'Events';
                    end
                else
                    app.Mainapp.DropDown_2.Items{1} = 'Non';
                    if isfield(app.Mainapp.Data,'Events')
                        app.Mainapp.DropDown_2.Items{2} = 'Events';
                        app.Mainapp.DropDown_2.Items{3} = 'Spikes';
                    else
                        app.Mainapp.DropDown_2.Items{2} = 'Spikes';
                    end
                    
                end
    
                Utility_Show_Info_Loaded_Data(app.Mainapp);

                %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Preprocessing",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
    
                %% Populate fields of this GUI with Dataset components
            
                % Get the field names of the structure
                fieldNames = fieldnames(app.Mainapp.Data);
                
                % Convert the field names to a single string
                fieldNamesString = strjoin(fieldNames, ', ');
                
                texttoshow = {};
                elements = 0;
                for i = 1:length(fieldNames)
                    if strcmp(fieldNames(i),"Raw") || strcmp(fieldNames(i),"Preprocessed")
                        elements = elements+1;
                        texttoshow{end+1} = strcat(num2str(elements),".: ",fieldNames{i}," Data");
                    elseif strcmp(fieldNames(i),"Events")
                        elements = elements+1;
                        texttoshow{end+1} = strcat(num2str(elements),".: ",fieldNames{i}," Data; Cell array with Dimension: ",num2str(size(app.Mainapp.Data.Events)));
                        for nevents = 1:length(app.Mainapp.Data.Events)
                            texttoshow{end+1} = [strcat("Event Nr ", num2str(nevents)," contains ",num2str(length(app.Mainapp.Data.Events{nevents}))," Events.")];
                        end
                        elements = elements+nevents;
                    elseif strcmp(fieldNames(i),"Spikes")
                        elements = elements+1;
                        SpikeFieldnames = fieldnames(app.Mainapp.Data.Spikes);
                        texttoshow{end+1} = strcat(num2str(elements),".: ",fieldNames{i}," Data; Structure with ",num2str(length(SpikeFieldnames))," Fields.");
                        for nspikefields = 1:length(SpikeFieldnames)
                            if strcmp(SpikeFieldnames{nspikefields},"SpikeTimes")
                                CurrentSpikeFieldname = SpikeFieldnames{nspikefields};
                                texttoshow{end+1} = strcat("Spike Field ", CurrentSpikeFieldname," contains ",num2str(size(app.Mainapp.Data.Spikes.(CurrentSpikeFieldname)))," Elements.");
                            end
                        end
                        elements = elements+1;
                    elseif strcmp(fieldNames(i),"Info")
                        elements = elements+1;
                        InfoFieldnames = fieldnames(app.Mainapp.Data.Info);
                        texttoshow{end+1} = strcat(num2str(elements-1),".: ",fieldNames{i}," Data; Structure with ",num2str(length(InfoFieldnames))," Fields.");
                    else
                        elements = elements+1;
                        CurrentFieldname = fieldNames{i};
                        texttoshow{end+1} = strcat(num2str(elements-1),".: ",fieldNames{i},"; Dimension: ",num2str(size(app.Mainapp.Data.(CurrentFieldname))));
                    end
                end
    
                texttoshow = ["Export or delete dataset components. Raw and preprocessed data can only be exported as .mat or using the 'Save Dataset' function of the 'Manage Dataset_ module as .dat (recommended) or .mat file. Standard folder the export data to is 'GUI_Path/Analysis Results'. The following dataset components where found:","",texttoshow];
                % Display the field names in the Text Area
                app.TextArea.Value = texttoshow;
    
                texttoshow = {};
                elements = 1;
    
                for i = 1:length(fieldNames)
                    if strcmp(fieldNames{i},'Spikes')
                        texttoshow{elements} = 'Spikes';
                        elements = elements+1;
                    else
                        texttoshow{elements} = fieldNames{i};
                        elements = elements+1;
                    end
                end
    
                app.DropDown.Items = texttoshow;
    
                value = app.DropDown.Value;
    
                % Export restrictions    
                if strcmp(value,"Raw") || strcmp(value,"Preprocessed")
                    app.ExportFormatDropDown.Items = {};
                    app.ExportFormatDropDown.Items{1} = '.mat';
                else
                    app.ExportFormatDropDown.Items = {};
                    app.ExportFormatDropDown.Items{1} = '.mat';
                    app.ExportFormatDropDown.Items{2} = '.txt';
                    app.ExportFormatDropDown.Items{3} = '.csv';
                end
                % Delete restrictions
                if strcmp(value,"Time") || strcmp(value,"Info") || strcmp(value,"TimeDownsampled") || strcmp(value,"Raw")
                    app.DeleteDatasetComponentButton.Enable = "off";
                else
                    app.DeleteDatasetComponentButton.Enable = "on";
                end
            end

            app.Mainapp.UIAxes.Box = "off";
        end

        % Button pushed function: ExportDatasetComponentButton
        function ExportDatasetComponentButtonPushed(app, event)
            Utility_Export_Dataset_Components(app.Mainapp.Data,app.DropDown.Value,app.ExportFormatDropDown.Value,app.Mainapp.executableFolder);
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;

            % Export restrictions    
            if strcmp(value,"Raw") || strcmp(value,"Preprocessed")
                app.ExportFormatDropDown.Items = {};
                app.ExportFormatDropDown.Items{1} = '.mat';
            else
                app.ExportFormatDropDown.Items = {};
                app.ExportFormatDropDown.Items{1} = '.mat';
                app.ExportFormatDropDown.Items{2} = '.txt';
                app.ExportFormatDropDown.Items{3} = '.csv';
            end
            % Delete restrictions
            if strcmp(value,"Time") || strcmp(value,"Info") || strcmp(value,"TimeDownsampled") || strcmp(value,"Raw")
                app.DeleteDatasetComponentButton.Enable = "off";
            else
                app.DeleteDatasetComponentButton.Enable = "on";
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ManageDatasetComponentsWindowUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {327, 327};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ManageDatasetComponentsWindowUIFigure = uifigure('Visible', 'off');
            app.ManageDatasetComponentsWindowUIFigure.Name = 'Manage Dataset Components Window';
            app.ManageDatasetComponentsWindowUIFigure.Icon = 'Logo.png';
            app.ManageDatasetComponentsWindowUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.ManageDatasetComponentsWindowUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.ManagementOptionsPanel = uipanel(app.RightPanel);
            app.ManagementOptionsPanel.Title = 'Management Options';
            app.DropDown = uidropdown(app.ManagementOptionsPanel);
            app.DropDown.Items = {};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = {};
            app.DeleteDatasetComponentButton = uibutton(app.ManagementOptionsPanel, 'push');
            app.DeleteDatasetComponentButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteDatasetComponentButtonPushed, true);
            app.DeleteDatasetComponentButton.Text = {'Delete Dataset'; 'Component'};
            app.ExportDatasetComponentButton = uibutton(app.ManagementOptionsPanel, 'push');
            app.ExportDatasetComponentButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDatasetComponentButtonPushed, true);
            app.ExportDatasetComponentButton.Text = {'Export Dataset'; 'Component'};
            app.ExportFormatDropDownLabel = uilabel(app.ManagementOptionsPanel);
            app.ExportFormatDropDownLabel.Text = 'Export Format';
            app.ExportFormatDropDown = uidropdown(app.ManagementOptionsPanel);
            app.ExportFormatDropDown.Items = {'.mat', '.txt', '.csv'};
            app.ExportFormatDropDown.Value = '.mat';
            app.ExportFormatDropDownLabel_2 = uilabel(app.ManagementOptionsPanel);
            app.ExportFormatDropDownLabel_2.Text = 'Component';
            app.TextArea = uitextarea(app.RightPanel);
            app.ManageDatasetComponentsWindowLabel = uilabel(app.RightPanel);
            app.ManageDatasetComponentsWindowLabel.Text = 'Manage Dataset Components Window';
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodLabel = uilabel(app.RightPanel);
            app.NeuromodLabel.Text = 'Neuromod';
            app.CloseButton = uibutton(app.RightPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.ManageDatasetComponentsWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Manage_Dataset_Components(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ManageDatasetComponentsWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ManageDatasetComponentsWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ManageDatasetComponentsWindowUIFigure)
        end
    end
end