classdef Manage_Modules_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ManageModulesWindowUIFigure  matlab.ui.Figure
        SelectedModuleDropDown       matlab.ui.control.DropDown
        SelectedModuleDropDownLabel  matlab.ui.control.Label
        RestoreStandardButton        matlab.ui.control.Button
        SetasnewdefaultButton        matlab.ui.control.Button
        SwitchandApplytoMainWindowButton  matlab.ui.control.Button
        Label                        matlab.ui.control.Label
        ListofSavedModulesTextArea   matlab.ui.control.TextArea
        ListofSavedModulesTextAreaLabel  matlab.ui.control.Label
        CreateNewModuleButton        matlab.ui.control.Button
        CloseButton                  matlab.ui.control.Button
        NeuromodToolboxLabel         matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        LoadDataWindowLabel          matlab.ui.control.Label
        CurrentModulesPanel          matlab.ui.container.Panel
        Panel                        matlab.ui.container.Panel
        RUNButton_5                  matlab.ui.control.Button
        ListBox_5                    matlab.ui.control.ListBox
        Panel_2                      matlab.ui.container.Panel
        RUNButton_4                  matlab.ui.control.Button
        ListBox_4                    matlab.ui.control.ListBox
        Panel_3                      matlab.ui.container.Panel
        ListBox_2                    matlab.ui.control.ListBox
        RUNButton_2                  matlab.ui.control.Button
        Panel_4                      matlab.ui.container.Panel
        RUNButton                    matlab.ui.control.Button
        ListBox                      matlab.ui.control.ListBox
        Label_2                      matlab.ui.control.Label
        SelectCheckBox_5             matlab.ui.control.CheckBox
        SelectCheckBox_4             matlab.ui.control.CheckBox
        SelectCheckBox_3             matlab.ui.control.CheckBox
        SelectCheckBox_2             matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        Mainapp = []; 
        Titles = [];
        Module = [];
        AppliedSwitch = 0;
        AppliedToMainapp = 0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            % Moce this window in the middle of thescreen
            movegui(app.ManageModulesWindowUIFigure, 'center');

            app.Mainapp = Mainapp;

            app.ListBox_5.Items = app.Mainapp.ListBox_6.Items;
            app.ListBox_4.Items = app.Mainapp.ListBox_3.Items;
            app.ListBox_2.Items = app.Mainapp.ListBox_2.Items;
            app.ListBox.Items = app.Mainapp.ListBox.Items;

            app.Panel.Title = app.Mainapp.MainPlotAnalysisModulePanel.Title;
            app.Panel_2.Title = app.Mainapp.ContinousDataModulePanel.Title;
            app.Panel_3.Title = app.Mainapp.EventDataModulePanel.Title;
            app.Panel_4.Title = app.Mainapp.SpikeModulePanel_2.Title;

            app.Titles{1} = app.Panel.Title;
            app.Titles{2} = app.Panel_2.Title;
            app.Titles{3} = app.Panel_3.Title;
            app.Titles{4} = app.Panel_4.Title;

            [app.Module,app] = All_Module_Items(app,0,[]);

            texttoshow = ["To add a new module click the button above directing you in the All_Module_Items.m function. There you have to add a new cell to the existing structure, entering title and items to show in your new module. Lastly add the function that should be executed when the user clicks the RUN button of your module.";"";"All modules found in All_Module_Items.m function:";""];
        
            for i = 1:length(app.Module)
                texttoshow = [texttoshow;convertCharsToStrings(app.Module{i}.Title)];
                app.SelectedModuleDropDown.Items{i} = app.Module{i}.Title;
            end

            texttoshow = [texttoshow;"";"To switch modules, select a module on the left that you want to replace with the module selected in dropdown menu below. Then click the 'Switch selected main window module with above selected modules' button to switch."];
            app.ListofSavedModulesTextArea.Value = texttoshow; 

        end

        % Button pushed function: RUNButton_5
        function RUNButton_5Pushed(app, event)
            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: SwitchandApplytoMainWindowButton
        function SwitchandApplytoMainWindowButtonPushed(app, event)
            [app] = Change_Modules_Apply_Module_Change_To_MainWindow(app);          
        end

        % Button pushed function: SetasnewdefaultButton
        function SetasnewdefaultButtonPushed(app, event)
            Change_Modules_Set_as_new_Default(app)
        end

        % Button pushed function: RestoreStandardButton
        function RestoreStandardButtonPushed(app, event)
            Change_Modules_Restore_Default(app);
        end

        % Value changed function: SelectCheckBox_2
        function SelectCheckBox_2ValueChanged(app, event)
            value = app.SelectCheckBox_2.Value;
            
        end

        % Button pushed function: CreateNewModuleButton
        function CreateNewModuleButtonPushed(app, event)
            Path = strcat(app.Mainapp.executableFolder,'\Modules\1. Main_Window_Functions\Manage_Modules_Functions\All_Module_Items.m');
            
            % Open the file in the editor
            edit(Path);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ManageModulesWindowUIFigure = uifigure('Visible', 'off');
            app.ManageModulesWindowUIFigure.Name = 'Manage Modules Window';
            app.ManageModulesWindowUIFigure.Icon = 'Logo.png';
            app.CurrentModulesPanel = uipanel(app.ManageModulesWindowUIFigure);
            app.CurrentModulesPanel.Title = 'Current Modules';
            app.SelectCheckBox_2 = uicheckbox(app.CurrentModulesPanel);
            app.SelectCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @SelectCheckBox_2ValueChanged, true);
            app.SelectCheckBox_2.Text = ' Select';
            app.SelectCheckBox_3 = uicheckbox(app.CurrentModulesPanel);
            app.SelectCheckBox_3.Text = ' Select';
            app.SelectCheckBox_4 = uicheckbox(app.CurrentModulesPanel);
            app.SelectCheckBox_4.Text = ' Select';
            app.SelectCheckBox_5 = uicheckbox(app.CurrentModulesPanel);
            app.SelectCheckBox_5.Text = ' Select';
            app.Label_2 = uilabel(app.CurrentModulesPanel);
            app.Label_2.Text = '>>';
            app.Panel_4 = uipanel(app.CurrentModulesPanel);
            app.ListBox = uilistbox(app.Panel_4);
            app.ListBox.Items = {};
            app.ListBox.Value = {};
            app.RUNButton = uibutton(app.Panel_4, 'push');
            app.RUNButton.Text = 'RUN';
            app.Panel_3 = uipanel(app.CurrentModulesPanel);
            app.RUNButton_2 = uibutton(app.Panel_3, 'push');
            app.RUNButton_2.Text = 'RUN';
            app.ListBox_2 = uilistbox(app.Panel_3);
            app.ListBox_2.Items = {};
            app.ListBox_2.Value = {};
            app.Panel_2 = uipanel(app.CurrentModulesPanel);
            app.ListBox_4 = uilistbox(app.Panel_2);
            app.ListBox_4.Items = {};
            app.ListBox_4.Value = {};
            app.RUNButton_4 = uibutton(app.Panel_2, 'push');
            app.RUNButton_4.Text = 'RUN';
            app.Panel = uipanel(app.CurrentModulesPanel);
            app.ListBox_5 = uilistbox(app.Panel);
            app.ListBox_5.Items = {};
            app.ListBox_5.Value = {};
            app.RUNButton_5 = uibutton(app.Panel, 'push');
            app.RUNButton_5.ButtonPushedFcn = createCallbackFcn(app, @RUNButton_5Pushed, true);
            app.RUNButton_5.Text = 'RUN';
            app.LoadDataWindowLabel = uilabel(app.ManageModulesWindowUIFigure);
            app.LoadDataWindowLabel.Text = 'Load Data Window';
            app.Image = uiimage(app.ManageModulesWindowUIFigure);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodToolboxLabel = uilabel(app.ManageModulesWindowUIFigure);
            app.NeuromodToolboxLabel.Text = 'Neuromod Toolbox';
            app.CloseButton = uibutton(app.ManageModulesWindowUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.CreateNewModuleButton = uibutton(app.ManageModulesWindowUIFigure, 'push');
            app.CreateNewModuleButton.ButtonPushedFcn = createCallbackFcn(app, @CreateNewModuleButtonPushed, true);
            app.CreateNewModuleButton.Text = 'Create New Module';
            app.ListofSavedModulesTextAreaLabel = uilabel(app.ManageModulesWindowUIFigure);
            app.ListofSavedModulesTextAreaLabel.Text = 'List of Saved Modules';
            app.ListofSavedModulesTextArea = uitextarea(app.ManageModulesWindowUIFigure);
            app.Label = uilabel(app.ManageModulesWindowUIFigure);
            app.Label.Text = '<<';
            app.SwitchandApplytoMainWindowButton = uibutton(app.ManageModulesWindowUIFigure, 'push');
            app.SwitchandApplytoMainWindowButton.ButtonPushedFcn = createCallbackFcn(app, @SwitchandApplytoMainWindowButtonPushed, true);
            app.SwitchandApplytoMainWindowButton.Text = {'Switch and '; 'Apply to Main Window'};
            app.SetasnewdefaultButton = uibutton(app.ManageModulesWindowUIFigure, 'push');
            app.SetasnewdefaultButton.ButtonPushedFcn = createCallbackFcn(app, @SetasnewdefaultButtonPushed, true);
            app.SetasnewdefaultButton.Text = 'Set as new default';
            app.RestoreStandardButton = uibutton(app.ManageModulesWindowUIFigure, 'push');
            app.RestoreStandardButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreStandardButtonPushed, true);
            app.RestoreStandardButton.Text = 'Restore Standard';
            app.SelectedModuleDropDownLabel = uilabel(app.ManageModulesWindowUIFigure);
            app.SelectedModuleDropDownLabel.Text = 'Selected Module';
            app.SelectedModuleDropDown = uidropdown(app.ManageModulesWindowUIFigure);
            app.SelectedModuleDropDown.Items = {};
            app.SelectedModuleDropDown.Value = {};
            % Show the figure after all components are created
            app.ManageModulesWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Manage_Modules_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ManageModulesWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ManageModulesWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ManageModulesWindowUIFigure)
        end
    end
end