classdef Set_LowPassFilterSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SetLowPassFilterSettingsUIFigure  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        RightPanel                     matlab.ui.container.Panel
        TextArea_3                     matlab.ui.control.TextArea
        TextArea_2                     matlab.ui.control.TextArea
        TextArea                       matlab.ui.control.TextArea
        Image                          matlab.ui.control.Image
        NeuromodLabel                  matlab.ui.control.Label
        SetLowPassFilterSettingsLabel  matlab.ui.control.Label
        MaxTimeSpansLabel_2            matlab.ui.control.Label
        MaxTimeSpansEditField          matlab.ui.control.EditField
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Mainapp = []; % Description
        WhatToDo = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function startupFcn(app, Mainapp, WhatToDo)
            movegui(app.SetLowPassFilterSettingsUIFigure, 'center');

            app.Mainapp = Mainapp;
            app.WhatToDo = WhatToDo;

            if strcmp(app.WhatToDo,"FilterOrder")
                app.MaxTimeSpansLabel_2.Text = "Filter Order (int)";
            elseif strcmp(app.WhatToDo,"Cutoff")
                app.MaxTimeSpansLabel_2.Text = "New Cutoff Frequency [Hz]";
            end
        end

        % Value changed function: MaxTimeSpansEditField
        function MaxTimeSpansEditFieldValueChanged(app, event)
            if strcmp(app.WhatToDo,"FilterOrder")
                app.Mainapp.FilterSettings.FilterOrder = app.MaxTimeSpansEditField.Value;
            elseif strcmp(app.WhatToDo,"Cutoff")
                app.Mainapp.FilterSettings.Cutoff = app.MaxTimeSpansEditField.Value;
            end
            
            delete(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SetLowPassFilterSettingsUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {184, 184};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SetLowPassFilterSettingsUIFigure = uifigure('Visible', 'off');
            app.SetLowPassFilterSettingsUIFigure.Name = 'Set Low Pass Filter Settings';
            app.SetLowPassFilterSettingsUIFigure.Icon = 'Logo.png';
            app.SetLowPassFilterSettingsUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.SetLowPassFilterSettingsUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.MaxTimeSpansEditField = uieditfield(app.RightPanel, 'text');
            app.MaxTimeSpansEditField.ValueChangedFcn = createCallbackFcn(app, @MaxTimeSpansEditFieldValueChanged, true);
            app.MaxTimeSpansLabel_2 = uilabel(app.RightPanel);
            app.MaxTimeSpansLabel_2.Text = 'Max Time Span [s]';
            app.SetLowPassFilterSettingsLabel = uilabel(app.RightPanel);
            app.SetLowPassFilterSettingsLabel.Text = 'Set Low Pass Filter Settings';
            app.NeuromodLabel = uilabel(app.RightPanel);
            app.NeuromodLabel.Text = 'Neuromod ';
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = 'Logo.png';
            app.TextArea = uitextarea(app.RightPanel);
            app.TextArea.Editable = 'off';
            app.TextArea.Enable = 'off';
            app.TextArea.Visible = 'off';
            app.TextArea_2 = uitextarea(app.RightPanel);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Enable = 'off';
            app.TextArea_2.Visible = 'off';
            app.TextArea_3 = uitextarea(app.RightPanel);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Enable = 'off';
            app.TextArea_3.Visible = 'off';
            % Show the figure after all components are created
            app.SetLowPassFilterSettingsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Set_LowPassFilterSettings(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SetLowPassFilterSettingsUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.SetLowPassFilterSettingsUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SetLowPassFilterSettingsUIFigure)
        end
    end
end