classdef Select_2D_3D_Plots < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        D3DPlotSelectionUIFigure     matlab.ui.Figure
        SaveSelectionButton          matlab.ui.control.Button
        NeuromodToolboxLabel         matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        InternalSpikeAnalysisWindowLabel  matlab.ui.control.Label
        SelectPlotTypeDropDown       matlab.ui.control.DropDown
        SelectPlotTypeDropDownLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        ExecutionWindow = []; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ExecutionWindow)
            movegui(app.D3DPlotSelectionUIFigure, 'center');

            app.ExecutionWindow = ExecutionWindow;
        end

        % Button pushed function: SaveSelectionButton
        function SaveSelectionButtonPushed(app, event)
            if strcmp(app.SelectPlotTypeDropDown.Value,"2D")
                app.ExecutionWindow.TwoORThreeD = "TwoD";
            elseif strcmp(app.SelectPlotTypeDropDown.Value,"3D")
                app.ExecutionWindow.TwoORThreeD = "ThreeD";
            end
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.D3DPlotSelectionUIFigure = uifigure('Visible', 'off');
            app.D3DPlotSelectionUIFigure.Name = '2D 3D Plot Selection';
            app.D3DPlotSelectionUIFigure.Icon = 'Logo.png';
            app.SelectPlotTypeDropDownLabel = uilabel(app.D3DPlotSelectionUIFigure);
            app.SelectPlotTypeDropDownLabel.Text = 'Select Plot Type ';
            app.SelectPlotTypeDropDown = uidropdown(app.D3DPlotSelectionUIFigure);
            app.SelectPlotTypeDropDown.Items = {'2D', '3D'};
            app.SelectPlotTypeDropDown.Value = '2D';
            app.InternalSpikeAnalysisWindowLabel = uilabel(app.D3DPlotSelectionUIFigure);
            app.InternalSpikeAnalysisWindowLabel.Text = 'Internal Spike Analysis Window';
            app.Image = uiimage(app.D3DPlotSelectionUIFigure);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodToolboxLabel = uilabel(app.D3DPlotSelectionUIFigure);
            app.NeuromodToolboxLabel.Text = 'Neuromod Toolbox';
            app.SaveSelectionButton = uibutton(app.D3DPlotSelectionUIFigure, 'push');
            app.SaveSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSelectionButtonPushed, true);
            app.SaveSelectionButton.Text = 'Save Selection';
            % Show the figure after all components are created
            app.D3DPlotSelectionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Select_2D_3D_Plots(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.D3DPlotSelectionUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.D3DPlotSelectionUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.D3DPlotSelectionUIFigure)
        end
    end
end