classdef TDT_LFP_or_AP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TDTDataSelectionUIFigure  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        RightPanel                matlab.ui.container.Panel
        InformationLabel          matlab.ui.control.Label
        ExtractLFPSignalButton    matlab.ui.control.Button
        ExtractAPSignalButton     matlab.ui.control.Button
        TextArea_3                matlab.ui.control.TextArea
        TextArea_2                matlab.ui.control.TextArea
        TextArea                  matlab.ui.control.TextArea
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        SelectedRecordings = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Moce this window in the middle of thescreen
            movegui(app.TDTDataSelectionUIFigure, 'center');            %% Predefine variables for the app to work
            
            app = Utility_Change_Light_Dark_Mode(app,"TDT_LFP_AP");

            TexttoShow = strcat("Found action potential and LFP data for TDT tank data. Only one can be loaded at a time. Please either 'AP Signal' or 'LFP Signal' to proceed with either one of these.");

            app.TextArea.Value = TexttoShow;
        end

        % Button pushed function: ExtractLFPSignalButton
        function ExtractLFPSignalButtonPushed(app, event)
            app.SelectedRecordings = 1; % 1 for LFP

            uiresume(app.TDTDataSelectionUIFigure);
        end

        % Button pushed function: ExtractAPSignalButton
        function ExtractAPSignalButtonPushed(app, event)
            app.SelectedRecordings = 2; % 2 for AP

            uiresume(app.TDTDataSelectionUIFigure);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.TDTDataSelectionUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {234, 234};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TDTDataSelectionUIFigure = uifigure('Visible', 'off');
            app.TDTDataSelectionUIFigure.Name = 'TDT Data Selection';
            app.TDTDataSelectionUIFigure.Icon = 'Logo.png';
            app.TDTDataSelectionUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.TDTDataSelectionUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.TextArea = uitextarea(app.RightPanel);
            app.TextArea_2 = uitextarea(app.RightPanel);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Enable = 'off';
            app.TextArea_2.Visible = 'off';
            app.TextArea_3 = uitextarea(app.RightPanel);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Enable = 'off';
            app.TextArea_3.Visible = 'off';
            app.ExtractAPSignalButton = uibutton(app.RightPanel, 'push');
            app.ExtractAPSignalButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractAPSignalButtonPushed, true);
            app.ExtractAPSignalButton.Text = 'Extract AP Signal';
            app.ExtractLFPSignalButton = uibutton(app.RightPanel, 'push');
            app.ExtractLFPSignalButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractLFPSignalButtonPushed, true);
            app.ExtractLFPSignalButton.Text = 'Extract LFP Signal';
            app.InformationLabel = uilabel(app.RightPanel);
            app.InformationLabel.Text = 'Information';
            % Show the figure after all components are created
            app.TDTDataSelectionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TDT_LFP_or_AP

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.TDTDataSelectionUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.TDTDataSelectionUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TDTDataSelectionUIFigure)
        end
    end
end