classdef Ask_ERD_Parameter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AskForEventRelatedDataExtractionUIFigure  matlab.ui.Figure
        ProceedButton                   matlab.ui.control.Button
        CloseButton                     matlab.ui.control.Button
        InformationLabel                matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        DataToExtractFromDropDown       matlab.ui.control.DropDown
        DataToExtractFromDropDownLabel  matlab.ui.control.Label
        EventChannelSelectionDropDown   matlab.ui.control.DropDown
        EventChannelSelectionDropDownLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        Data = []; % Description
        TimearoundEvent = [];
        EventTime = [];
        ERDParameter = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Data, EventDataType)
            movegui(app.AskForEventRelatedDataExtractionUIFigure, 'center');

            app.Data = Data;

            app = Utility_Change_Light_Dark_Mode(app,"Ask_For_ERD_Parameter");
            
            if strcmp(EventDataType,'Preprocessed Event Related Data')
                app.TextArea.Value = "Preprocessed event related data is only extracted 'on the fly' based on the two parameters on the right of this window. To extract event related data and save it, you have to specify whether to extract it from the raw or preprocessed dataset and which event channel you want to use for trigger time information.";
            else
                app.TextArea.Value = "Event related data is only extracted 'on the fly' based on the two parameters on the right of this window. To extract event related data and save it, you have to specify whether to extract it from the raw or preprocessed dataset and which event channel you want to use for trigger time information.";
            end

            %% -------------------- Set UP Window General things-------------------- 
            if isfield(Data,'Preprocessed')
                app.DataToExtractFromDropDown.Items{1} = 'Raw Data';
                app.DataToExtractFromDropDown.Items{2} = 'Preprocessed Data';
            else
                app.DataToExtractFromDropDown.Items{1} = 'Raw Data';
            end
            
            if strcmp(EventDataType,'Preprocessed Event Related Data')
                PreproEventChannelNames = strings();
                if isfield(Data.Info.EventRelatedPreprocessing,'TrialRejectionEventChannelNames')
                    names = split(Data.Info.EventRelatedPreprocessing.TrialRejectionEventChannelNames, ",");            % split into string array
                    uniqueNames = unique(names);        % get unique names
                    for i = 1:length(uniqueNames)
                        PreproEventChannelNames = [PreproEventChannelNames,uniqueNames{i}];
                    end
                end
                if isfield(Data.Info.EventRelatedPreprocessing,'ChannelRejectionEventChannelNames')
                    names = split(Data.Info.EventRelatedPreprocessing.ChannelRejectionEventChannelNames, ",");            % split into string array
                    uniqueNames = unique(names);        % get unique names

                    PreproEventChannelNames = [PreproEventChannelNames,uniqueNames{i}];
                end

                PreproEventChannelNames = unique(PreproEventChannelNames);        % get unique names

                for i = 1:length(PreproEventChannelNames)
                    if ~isempty(PreproEventChannelNames{i})
                        app.EventChannelSelectionDropDown.Items{end+1} = convertStringsToChars(PreproEventChannelNames{i});
                    end
                end
            else
                for i = 1:length(Data.Info.EventChannelNames)
                    app.EventChannelSelectionDropDown.Items{i} = convertStringsToChars(Data.Info.EventChannelNames{i});
                end
            end

            spaceindicie = strfind(Data.Info.EventRelatedDataTimeRange," ");
            app.TimearoundEvent(1) = str2double(Data.Info.EventRelatedDataTimeRange(1:spaceindicie(1)-1));
            app.TimearoundEvent(2) = str2double(Data.Info.EventRelatedDataTimeRange(spaceindicie(1)+1:end));

            if strcmp(app.DataToExtractFromDropDown.Value,"Preprocessed Data") && isfield(Data.Info,'DownsampledSampleRate')
                app.EventTime = 0-app.TimearoundEvent(1):1/Data.Info.DownsampledSampleRate:app.TimearoundEvent(2);
            else
                app.EventTime = 0-app.TimearoundEvent(1):1/Data.Info.NativeSamplingRate:app.TimearoundEvent(2);
            end


        end

        % Button pushed function: ProceedButton
        function ProceedButtonPushed(app, event)
            %% -------------------- Extract Event Related Data -------------------- 
            % if isfield(EventDataType,'PreprocessedEventRelatedData')
            %     [Data,~] = Event_Module_Extract_Event_Related_Data(Data,app.EventChannelSelectionDropDown.Value,app.TimearoundEvent,app.DataToExtractFromDropDown.Value,"Preprocessed Event Related Data");
            % else
            %     [Data,~] = Event_Module_Extract_Event_Related_Data(Data,app.EventChannelSelectionDropDown.Value,app.TimearoundEvent,app.DataToExtractFromDropDown.Value,"Raw Event Related Data");
            % end
            
            app.ERDParameter.DatatoExtractFrom = app.DataToExtractFromDropDown.Value;
            app.ERDParameter.EventChannel = app.EventChannelSelectionDropDown.Value;
            app.ERDParameter.EventTime = app.EventTime;
            app.ERDParameter.TimearoundEvent = app.TimearoundEvent;

            uiresume(app.AskForEventRelatedDataExtractionUIFigure);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.ERDParameter = [];

            uiresume(app.AskForEventRelatedDataExtractionUIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AskForEventRelatedDataExtractionUIFigure = uifigure('Visible', 'off');
            app.AskForEventRelatedDataExtractionUIFigure.Name = 'Ask For Event Related Data Extraction';
            app.AskForEventRelatedDataExtractionUIFigure.Icon = 'Logo.png';
            app.EventChannelSelectionDropDownLabel = uilabel(app.AskForEventRelatedDataExtractionUIFigure);
            app.EventChannelSelectionDropDownLabel.Text = 'Event Channel Selection';
            app.EventChannelSelectionDropDown = uidropdown(app.AskForEventRelatedDataExtractionUIFigure);
            app.EventChannelSelectionDropDown.Items = {};
            app.EventChannelSelectionDropDown.Value = {};
            app.DataToExtractFromDropDownLabel = uilabel(app.AskForEventRelatedDataExtractionUIFigure);
            app.DataToExtractFromDropDownLabel.Text = 'Data To Extract From';
            app.DataToExtractFromDropDown = uidropdown(app.AskForEventRelatedDataExtractionUIFigure);
            app.DataToExtractFromDropDown.Items = {};
            app.DataToExtractFromDropDown.Value = {};
            app.TextArea = uitextarea(app.AskForEventRelatedDataExtractionUIFigure);
            app.TextArea.Value = {'Event related data is only extracted ''on the fly'' based on the two parameters on the right of this window. To extract event related data and save it, you have to specify whether to extract it from the raw or preprocessed dataset and which event channel you want to use for trigger time information.'};
            app.InformationLabel = uilabel(app.AskForEventRelatedDataExtractionUIFigure);
            app.InformationLabel.Text = 'Information';
            app.CloseButton = uibutton(app.AskForEventRelatedDataExtractionUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.ProceedButton = uibutton(app.AskForEventRelatedDataExtractionUIFigure, 'push');
            app.ProceedButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedButtonPushed, true);
            app.ProceedButton.Text = 'Proceed';
            % Show the figure after all components are created
            app.AskForEventRelatedDataExtractionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Ask_ERD_Parameter(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.AskForEventRelatedDataExtractionUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.AskForEventRelatedDataExtractionUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AskForEventRelatedDataExtractionUIFigure)
        end
    end
end