classdef Save_Data_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SaveDataWindowUIFigure  matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        SaveOptionsPanel        matlab.ui.container.Panel
        SaveTypeDropDown_2      matlab.ui.control.DropDown
        SaveforLabel            matlab.ui.control.Label
        SaveOptionsLabel        matlab.ui.control.Label
        SaveButton              matlab.ui.control.Button
        SelectAllButton         matlab.ui.control.StateButton
        PreprocessedEventRelatedDataButton  matlab.ui.control.StateButton
        SaveTypeDropDown        matlab.ui.control.DropDown
        SaveasDropDownLabel     matlab.ui.control.Label
        MultipleSelectionsPossibleLabel  matlab.ui.control.Label
        EventRelatedDataButton  matlab.ui.control.StateButton
        WhattosaveLabel         matlab.ui.control.Label
        SpikeIndiciesButton     matlab.ui.control.StateButton
        EventTimesButton        matlab.ui.control.StateButton
        PreprocessedDataButton  matlab.ui.control.StateButton
        RawDataButton           matlab.ui.control.StateButton
        RightPanel              matlab.ui.container.Panel
        TextArea                matlab.ui.control.TextArea
        InformationLabel        matlab.ui.control.Label
        CloseButton             matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Mainapp = []; % To save Main Window object
        Whattosave = []; % To capture, which datacomponents have to be saved
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            movegui(app.SaveDataWindowUIFigure, 'center');

            app.TextArea.Value = ["Please select a file type and data components you want to save. Dataset component can be added by clicking the corresponding button on the left (sticky selection).";"Raw Data always has to be saved.";"File type .dat is recommended, since its faster to save and load and requires less memory than .mat files.";" "; "If saved as .dat file, only raw and preprocessed dataset are saved in that format. Recordings infos, events, spikes and so on are save in a additional file called NameOfYourRecording_Info.mat (same name as the .dat file with an 'Info' at the end)."];
            app.TextArea.Value = [app.TextArea.Value;"";"When data is saved in 'Path_to_GUI/Recording Data/Saved GUI Data', it is autodected on startup of the load data window and can be immediately loaded."];
            app.Mainapp = Mainapp;
            
            app = Utility_Change_Light_Dark_Mode(app,"Save_Data_Window");
            % Initiate vectzor holding Info what should be saved. Each
            % indicie of Whattosave corresponds to a component of the
            % dataset. A 0 indicates, this component should not be saved.
            % Vector gets changed when user changes the selection. 
            % Components in the correct order are:
            % [Raw,Preprocessed,Events,Spikes,EventrelatedData] --> [1,1,1,0,0] saves raw data, preprocessed data and event time points

            app.Whattosave = zeros(1,6);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            %% Check if data is saved or just events, spikes or info. If no
            % data display warning
           
            %% Start Saving Data
            app.TextArea.Value = "Data is saved, please wait until this window closes";
            pause(0.2);
            
            if strcmp(app.SaveTypeDropDown_2.Value,"NeuroMod")
                [filepath,Error] = Manage_Dataset_Module_SaveData(app.Mainapp.Data,app.SaveTypeDropDown.Value,app.Whattosave,app.Mainapp.executableFolder,"No");
            elseif strcmp(app.SaveTypeDropDown_2.Value,"Other")
                if app.RawDataButton.Value == 1
                    Manage_Dataset_SaveData_NWB(app.Mainapp.Data,app.SaveTypeDropDown.Value,"Raw Data")
                else
                    Manage_Dataset_SaveData_NWB(app.Mainapp.Data,app.SaveTypeDropDown.Value,"Preprocessed Data")
                end
            elseif strcmp(app.SaveTypeDropDown_2.Value,"NEO")
                if strcmp(app.SaveTypeDropDown.Value,"Neo .mat file")
                    if app.Whattosave(1) == 1
                        Manage_Dataset_SaveData_NeoMAT(app.Mainapp.Data,app.Mainapp.Data.Info.NativeSamplingRate,"Raw Data",app.Whattosave(3),app.Whattosave(4),app.Mainapp.Data.Time)
                    else
                        if isfield(app.Mainapp.Data.Info,'DownsampledSampleRate')
                            Manage_Dataset_SaveData_NeoMAT(app.Mainapp.Data,app.Mainapp.Data.Info.DownsampledSampleRate,"Preprocessed Data",app.Whattosave(3),app.Whattosave(4),app.Mainapp.Data.TimeDownsampled)
                        else
                            Manage_Dataset_SaveData_NeoMAT(app.Mainapp.Data,app.Mainapp.Data.Info.NativeSamplingRate,"Preprocessed Data",app.Whattosave(3),app.Whattosave(4),app.Mainapp.Data.Time)
                        end
                    end
                end
            end

            %% Check if Saving was succesfull.
            if ~isempty(filepath) && Error == 0
                app.TextArea.Value = strcat("Data was succesfully saved to: ",filepath);
                pause(0.2);
            else
                app.TextArea.Value = "Saving Data failed. Check if you made a selection of data components";
                pause(0.2);
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            try
                app.Mainapp.SaveDataWindow = [];
            end
            delete(app);
        end

        % Value changed function: RawDataButton
        function RawDataButtonValueChanged(app, event)
            % Check if Raw Data is part of the dataset. If yes, set
            % Whattosave(1,1) to 1, otherwise to 0
            if ~strcmp(app.SaveTypeDropDown_2.Value,"NeuroMod")
                if app.PreprocessedDataButton.Value == 1
                    app.PreprocessedDataButton.Value = 0;
                end
            end
            

            value = app.RawDataButton.Value;
            if value == 1
                if ~isfield(app.Mainapp.Data,"Raw")
                    f = msgbox("No raw data found!");
                    app.RawDataButton.Value = 0;
                else
                    app.Whattosave(1,1) = 1;
                end
            else
                app.Whattosave(1,1) = 0;
            end
         
        end

        % Value changed function: PreprocessedDataButton
        function PreprocessedDataButtonValueChanged(app, event)
            % Check if Preprocessed Data is part of the dataset. If yes, set
            % Whattosave(1,2) to 1, otherwise to 0

            if ~strcmp(app.SaveTypeDropDown_2.Value,"NeuroMod")
                if ~isfield(app.Mainapp.Data,"Preprocessed")
                    msgbox("No preprocessed data found!");
                    app.PreprocessedDataButton.Value = 0;
                    return;
                end

                if app.RawDataButton.Value == 1
                    app.RawDataButton.Value = 0;
                end
            end
            
      
            value = app.PreprocessedDataButton.Value;
            if value == 1
                if ~isfield(app.Mainapp.Data,"Preprocessed")
                    f = msgbox("No preprocessed data found!");
                    app.PreprocessedDataButton.Value = 0;
                else
                    app.Whattosave(1,2) = 1;
                end
            else
                app.Whattosave(1,2) = 0;
            end
           
        end

        % Value changed function: EventTimesButton
        function EventTimesButtonValueChanged(app, event)
            % Check if Event Data is part of the dataset. If yes, set
            % Whattosave(1,3) to 1, otherwise to 0
           
            value = app.EventTimesButton.Value;
            if value == 1
                if ~isfield(app.Mainapp.Data,"Events")
                    f = msgbox("No event data found!");
                    app.EventTimesButton.Value = 0;
                else
                    app.Whattosave(1,3) = 1;
                end
            else
                app.Whattosave(1,3) = 0;
                
                app.Whattosave(1,5) = 0;
                app.Whattosave(1,6) = 0;
                app.EventRelatedDataButton.Value = 0;
                app.PreprocessedEventRelatedDataButton.Value = 0;
            end
          
        end

        % Value changed function: SpikeIndiciesButton
        function SpikeIndiciesButtonValueChanged(app, event)
            % Check if Spike Data is part of the dataset. If yes, set
            % Whattosave(1,4) to 1, otherwise to 0
       
            value = app.SpikeIndiciesButton.Value;
            if value == 1
                if ~isfield(app.Mainapp.Data,"Spikes")
                    f = msgbox("No spike data found!");
                    app.SpikeIndiciesButton.Value = 0;
                else
                    app.Whattosave(1,4) = 1;
                end
            else
                app.Whattosave(1,4) = 0;
            end
          
        end

        % Value changed function: SelectAllButton
        function SelectAllButtonValueChanged(app, event)
            % Check existence of all data components and activate the ones
            % that are present 

            value = app.SelectAllButton.Value;
            if value == 1
                if isfield(app.Mainapp.Data,"Raw")
                    app.RawDataButton.Value = 1;
                    app.Whattosave(1,1) = 1;
                end
                if isfield(app.Mainapp.Data,"Preprocessed") && strcmp(app.SaveTypeDropDown_2.Value,"NeuroMod")
                    app.PreprocessedDataButton.Value = 1;
                    app.Whattosave(1,2) = 1;
                end
                if isfield(app.Mainapp.Data,"Events")
                    app.EventTimesButton.Value = 1;
                    app.Whattosave(1,3) = 1;
                end
                if isfield(app.Mainapp.Data,"Spikes")
                    app.SpikeIndiciesButton.Value = 1;
                    app.Whattosave(1,4) = 1;
                end
                if isfield(app.Mainapp.Data,"Events") && ~strcmp(app.SaveTypeDropDown_2.Value,"NeuroMod")
                    app.EventRelatedDataButton.Value = 1;
                    app.Whattosave(1,5) = 1;
                end
                if isfield(app.Mainapp.Data.Info,"EventRelatedPreprocessing") && ~strcmp(app.SaveTypeDropDown_2.Value,"NeuroMod")
                    app.PreprocessedEventRelatedDataButton.Value = 1;
                    app.Whattosave(1,6) = 1;
                end

            else
                app.RawDataButton.Value = 0;
                app.PreprocessedDataButton.Value = 0;
                app.EventTimesButton.Value = 0;
                app.SpikeIndiciesButton.Value = 0;
                app.EventRelatedDataButton.Value = 0;
                app.Whattosave(1,1:6) = 0;
            end
           

            app.SelectAllButton.Value = 0;

        end

        % Value changed function: EventRelatedDataButton
        function EventRelatedDataButtonValueChanged(app, event)
            % Check if Event Related Data is part of the dataset. If yes, set
            % Whattosave(1,5) to 1, otherwise to 0
            if strcmp(app.SaveTypeDropDown_2.Value,"NeuroMod")
                value = app.EventRelatedDataButton.Value;
                if value == 1
                    if ~isfield(app.Mainapp.Data,"Events")
                        msgbox("No event trigger data found!");
                        app.EventRelatedDataButton.Value = 0;
                    else
                        if app.EventTimesButton.Value == 0
                            app.EventTimesButton.Value = 1;
                            app.Whattosave(1,3) = 1;
                            disp("Automatically enabled to save event trigger data since its necessray for the GUI to work with event related data!")
                        end
                        app.Whattosave(1,5) = 1;
                    end
                else
                    app.Whattosave(1,5) = 0;
    
                    if app.PreprocessedEventRelatedDataButton.Value == 1
                        app.PreprocessedEventRelatedDataButton.Value = 0;
                        app.Whattosave(1,6) = 0;
                        disp("Automatically disabled to save preprocessed event data since raw event related data is necessray for it to work properly in the GUI!")
                    end
                end
            end
        end

        % Value changed function: PreprocessedEventRelatedDataButton
        function PreprocessedEventRelatedDataButtonValueChanged(app, event)
            value = app.PreprocessedEventRelatedDataButton.Value;
            % Check if Event Data is part of the dataset. If yes, set
            % Whattosave(1,6) to 1, otherwise to 0
            if strcmp(app.SaveTypeDropDown_2.Value,"NeuroMod")
                if value == 1
                    if ~isfield(app.Mainapp.Data.Info,"EventRelatedPreprocessing")
                        f = msgbox("No preprocessing information for event related data found!");
                        app.PreprocessedEventRelatedDataButton.Value = 0;
                    else
                        if app.EventTimesButton.Value == 0
                            app.EventTimesButton.Value = 1;
                            app.Whattosave(1,3) = 1;
                            disp("Automatically enabled to save event trigger data since its necessray for the GUI to work with preprocesed event related data!")
                        end
                        if app.EventRelatedDataButton.Value == 0
                            app.EventRelatedDataButton.Value = 1;
                            app.Whattosave(1,5) = 1;
                            disp("Automatically enabled to save raw event event related data since its necessray for preprocessed event related data to work in th GUI!")
                        end
                        app.Whattosave(1,6) = 1;
                    end
                else
                    app.Whattosave(1,6) = 0;
                end
            end
        end

        % Close request function: SaveDataWindowUIFigure
        function SaveDataWindowUIFigureCloseRequest(app, event)
            try
                app.Mainapp.SaveDataWindow = [];
            end
            delete(app);
        end

        % Value changed function: SaveTypeDropDown_2
        function SaveTypeDropDown_2ValueChanged(app, event)
            app.Whattosave = zeros(1,6);
            app.RawDataButton.Value = 0;
            app.PreprocessedDataButton.Value = 0;
            app.SpikeIndiciesButton.Value = 0;

            app.EventRelatedDataButton.Value = 0;
            app.EventTimesButton.Value = 0;
            app.PreprocessedEventRelatedDataButton.Value = 0;

            value = app.SaveTypeDropDown_2.Value;
            
            if strcmp(value,"NEO")
                app.SaveTypeDropDown.Items = {};
                app.SaveTypeDropDown.Items{1} = 'Neo .mat file';
                app.SaveTypeDropDown.Items{2} = 'HDF5';

                app.SpikeIndiciesButton.Enable = "on";
                app.EventRelatedDataButton.Enable = "off";
                app.EventTimesButton.Enable = "on";
                app.PreprocessedEventRelatedDataButton.Enable = "off";
            elseif strcmp(value,"NeuroMod")
                app.SaveTypeDropDown.Items = {};
                app.SaveTypeDropDown.Items{1} = '.dat';
                app.SaveTypeDropDown.Items{2} = '.mat';

                app.SpikeIndiciesButton.Enable = "on";
                app.EventRelatedDataButton.Enable = "on";
                app.EventTimesButton.Enable = "on";
                app.PreprocessedEventRelatedDataButton.Enable = "on";
            elseif strcmp(value,"Other")
                app.SaveTypeDropDown.Items = {};
                app.SaveTypeDropDown.Items{1} = 'NWB (Neuroscience without borders)';
                app.SaveTypeDropDown.Items{2} = 'SpikeInterface Numpy Object';

                app.SpikeIndiciesButton.Enable = "off";
                app.EventRelatedDataButton.Enable = "off";
                app.EventTimesButton.Enable = "off";
                app.PreprocessedEventRelatedDataButton.Enable = "off";
            end

            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SaveDataWindowUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {459, 459};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {291, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SaveDataWindowUIFigure = uifigure('Visible', 'off');
            app.SaveDataWindowUIFigure.Name = 'Save Data Window';
            app.SaveDataWindowUIFigure.Icon = 'Logo.png';
            app.SaveDataWindowUIFigure.CloseRequestFcn = createCallbackFcn(app, @SaveDataWindowUIFigureCloseRequest, true);
            app.SaveDataWindowUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.SaveDataWindowUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SaveOptionsPanel = uipanel(app.LeftPanel);
            app.SaveOptionsPanel.Title = 'Save Options';
            app.RawDataButton = uibutton(app.SaveOptionsPanel, 'state');
            app.RawDataButton.ValueChangedFcn = createCallbackFcn(app, @RawDataButtonValueChanged, true);
            app.RawDataButton.Text = 'Raw Data';
            app.PreprocessedDataButton = uibutton(app.SaveOptionsPanel, 'state');
            app.PreprocessedDataButton.ValueChangedFcn = createCallbackFcn(app, @PreprocessedDataButtonValueChanged, true);
            app.PreprocessedDataButton.Text = 'Preprocessed Data';
            app.EventTimesButton = uibutton(app.SaveOptionsPanel, 'state');
            app.EventTimesButton.ValueChangedFcn = createCallbackFcn(app, @EventTimesButtonValueChanged, true);
            app.EventTimesButton.Text = 'Events';
            app.SpikeIndiciesButton = uibutton(app.SaveOptionsPanel, 'state');
            app.SpikeIndiciesButton.ValueChangedFcn = createCallbackFcn(app, @SpikeIndiciesButtonValueChanged, true);
            app.SpikeIndiciesButton.Text = 'Spike Indicies';
            app.WhattosaveLabel = uilabel(app.SaveOptionsPanel);
            app.WhattosaveLabel.Text = 'What to save';
            app.EventRelatedDataButton = uibutton(app.SaveOptionsPanel, 'state');
            app.EventRelatedDataButton.ValueChangedFcn = createCallbackFcn(app, @EventRelatedDataButtonValueChanged, true);
            app.EventRelatedDataButton.Text = 'Event Related Data';
            app.MultipleSelectionsPossibleLabel = uilabel(app.SaveOptionsPanel);
            app.MultipleSelectionsPossibleLabel.Text = 'Multiple Selections Possible';
            app.SaveasDropDownLabel = uilabel(app.SaveOptionsPanel);
            app.SaveasDropDownLabel.Text = 'Save as';
            app.SaveTypeDropDown = uidropdown(app.SaveOptionsPanel);
            app.SaveTypeDropDown.Items = {'.mat', '.dat'};
            app.SaveTypeDropDown.Value = '.dat';
            app.PreprocessedEventRelatedDataButton = uibutton(app.SaveOptionsPanel, 'state');
            app.PreprocessedEventRelatedDataButton.ValueChangedFcn = createCallbackFcn(app, @PreprocessedEventRelatedDataButtonValueChanged, true);
            app.PreprocessedEventRelatedDataButton.Text = {'Preprocessed Event'; 'Related Data'};
            app.SelectAllButton = uibutton(app.SaveOptionsPanel, 'state');
            app.SelectAllButton.ValueChangedFcn = createCallbackFcn(app, @SelectAllButtonValueChanged, true);
            app.SelectAllButton.Text = 'Select All';
            app.SaveButton = uibutton(app.SaveOptionsPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SaveOptionsLabel = uilabel(app.SaveOptionsPanel);
            app.SaveOptionsLabel.Text = 'Save Options';
            app.SaveforLabel = uilabel(app.SaveOptionsPanel);
            app.SaveforLabel.Text = 'Save for';
            app.SaveTypeDropDown_2 = uidropdown(app.SaveOptionsPanel);
            app.SaveTypeDropDown_2.Items = {'NeuroMod', 'NEO', 'Other'};
            app.SaveTypeDropDown_2.ValueChangedFcn = createCallbackFcn(app, @SaveTypeDropDown_2ValueChanged, true);
            app.SaveTypeDropDown_2.Value = 'NeuroMod';
            app.RightPanel = uipanel(app.GridLayout);
            app.CloseButton = uibutton(app.RightPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.InformationLabel = uilabel(app.RightPanel);
            app.InformationLabel.Text = 'Information';
            app.TextArea = uitextarea(app.RightPanel);
            app.TextArea.Editable = 'off';
            % Show the figure after all components are created
            app.SaveDataWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Save_Data_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SaveDataWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.SaveDataWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SaveDataWindowUIFigure)
        end
    end
end