classdef Load_Data_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LoadDataWindowUIFigure       matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        LoadDataWindowLabel          matlab.ui.control.Label
        NeuromodLabel                matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        LoadingOptionsPanel          matlab.ui.container.Panel
        DataFormatSelectionLabel     matlab.ui.control.Label
        SaveFileSelectionLabel       matlab.ui.control.Label
        LoadButton                   matlab.ui.control.Button
        DropDown_2                   matlab.ui.control.DropDown
        DropDown                     matlab.ui.control.DropDown
        SelectDifferentFolderButton  matlab.ui.control.Button
        RightPanel                   matlab.ui.container.Panel
        InformationLabel             matlab.ui.control.Label
        TextArea                     matlab.ui.control.TextArea
        CloseButton                  matlab.ui.control.Button
        TextArea_2                   matlab.ui.control.TextArea
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Mainapp = []; % Description
        SelectedFolder = [];
        DataMatfileindex = [];
        DataDATfileindex = [];
        Infofileindex = [];
        InfoFiletoLoad = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            % Moce this window in the middle of thescreen
            movegui(app.LoadDataWindowUIFigure, 'center');
            app.Mainapp = Mainapp;

            %% Analyze Folder components (standard folder = GUI Path/SavedData)
            [app.SelectedFolder,app.DataMatfileindex,app.DataDATfileindex,app.Infofileindex,app.InfoFiletoLoad,Formatsfound,DropDownMenuChar,DropDownMenu_2Char,stringArray] = Manage_Dataset_Module_LoadGUIDataCheckFolderContents(app.Mainapp.executableFolder,[]);

            if isempty(Formatsfound)
                TextToShow = strcat("Autosearched Folder ",app.SelectedFolder," contains no valid .dat or .mat files.");
                TextToShow = [TextToShow;"Please select a folder with saved files manually. Saving data as .dat file also saves a Info.mat file that has to be present (and match the name of the .dat file with an 'Info' at the end) to be detected as a valid save file."];
                app.TextArea.Value = [TextToShow;"";"If proper save files are found in the selected folder, you will see the folder contents in this text area and can select the save file name on the left."];
                return;
            end

            %% Show folder components in GUI
            app.DropDown.Items = DropDownMenuChar;
            app.DropDown_2.Items = DropDownMenu_2Char;
            
            app.TextArea.Value = ["Auto-searched path:";"";app.SelectedFolder];
            app.TextArea.Value = [app.TextArea.Value;"";"Saved GUI Data was detected";"";"Folder Conents:";stringArray];
            
        end

        % Button pushed function: SelectDifferentFolderButton
        function SelectDifferentFolderButtonPushed(app, event)
            %% Get user selcted folder and save as app.Load_Data_Window_Info.selectedFolder
            % Prompt the user to select a folder
            
           
            app.SelectedFolder = [];
            app.DataMatfileindex = [];
            app.DataDATfileindex = [];
            app.Infofileindex = [];
            app.InfoFiletoLoad = [];

            Placeholder = {};
            app.DropDown_2.Items = Placeholder;

            NewFolderSelection = uigetdir('Select a folder with your recording data');
            if NewFolderSelection == 0
                disp('No folder selected. Exiting.');
                app.TextArea.Value = 'No folder selected or no suitable data format found within folder.';
                return;
            end
            
           %% Analyze Folder components (standard folder = GUI Path/SavedData)
            [app.SelectedFolder,app.DataMatfileindex,app.DataDATfileindex,app.Infofileindex,app.InfoFiletoLoad,Formatsfound,DropDownMenuChar,DropDownMenu_2Char,stringArray] = Manage_Dataset_Module_LoadGUIDataCheckFolderContents(app.Mainapp.executableFolder,NewFolderSelection);

            if isempty(app.DataDATfileindex) && isempty(app.DataMatfileindex)
                stringtoshow = strcat("Warning: No supported file types found in ",app.SelectedFolder);
                stringtoshow = [stringtoshow; " Make sure the folder contains valid .mat or .dat files saved with this GUI."];
                app.TextArea.Value = stringtoshow;
                %msgbox(stringtoshow);
                return;
            end

            %% Show folder components in GUI
            if ~isempty(DropDownMenuChar{1})
                app.DropDown.Items = DropDownMenuChar;
            end
            if ~isempty(DropDownMenu_2Char{1})
                app.DropDown_2.Items = DropDownMenu_2Char;
            end

            app.TextArea.Value = ["Path:";"";app.SelectedFolder];
            app.TextArea.Value = [app.TextArea.Value;"";"Folder Contents:";"";stringArray];
            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            
            if isempty(app.DropDown_2.Value)
                msgbox("Error: no valid file selected!")
                return;
            end

            Format = app.DropDown.Value;
            File = app.DropDown_2.Value;
            FullPathData = fullfile(app.SelectedFolder,File);
            if ~isempty(app.InfoFiletoLoad)
                FullPathInfo = fullfile(app.SelectedFolder,app.InfoFiletoLoad);
            else
                FullPathInfo = [];
            end

            if isempty(FullPathInfo) && ~strcmp(app.DropDown.Value,".mat")
                f = msgbox("No Info file found with the same name as data file +_Info. Returning");
                return;
            end

            [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Initial");

            [app.Mainapp.Data] = Manage_Dataset_Module_LoadData(Format,FullPathData,FullPathInfo,app.TextArea);

            if ~isempty(app.Mainapp.Data)
                [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Loading");
            else
                return;
            end
            
            Organize_Delete_All_Open_Windows(app.Mainapp,1);

            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);

            Utility_Initialize_Clicks_Plots(app.Mainapp,"Static");

            app.Mainapp.ProbeViewWindowHandle = Probe_View_Window(app.Mainapp,'MainWindow');

            [app.Mainapp] = Utility_Set_ToolTips(app.Mainapp,app.Mainapp.ShowToolTipsSetting,"ProbeViewMainWindow");
            
            delete(app);

        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.Mainapp.LoadDataWindow = [];
            delete(app);
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            %% When data format is changed, filenames that have to be displayed change. If changed to .mat files, all .mat files have to be displayed
            value = app.DropDown.Value;
            Placeholder = {};

            app.DropDown_2.Items = Placeholder;

            [stringArray] = Utility_Extract_Contents_of_Folder(app.SelectedFolder);

            Indextodelete = [];
            for i = 1:length(stringArray)
                if ~strcmp(stringArray(i),"")
                    if ~contains(stringArray(i),'.')
                        Indextodelete = [Indextodelete,i];
                    end
                end
            end
            if ~isempty(Indextodelete)
                stringArray(Indextodelete) = [];
            end

            if strcmp(value,".mat")

                if ~isempty(app.DataMatfileindex)
                    for i = 1:length(app.DataMatfileindex)
                        app.DropDown_2.Items{i} = convertStringsToChars(stringArray(app.DataMatfileindex(i)));
                    end
                end

            elseif strcmp(value,".dat")
    
                if ~isempty(app.DataDATfileindex)
                    for i = 1:length(app.DataDATfileindex)
                        app.DropDown_2.Items{i} = convertStringsToChars(stringArray(app.DataDATfileindex(i)));
                    end
                end
            end

            if ~strcmp(app.DropDown_2.Value(end-3:end),".mat")
                for i = 1:length(app.Infofileindex)
                    Infofilename = convertStringsToChars(stringArray(app.Infofileindex(i)));
                    if strcmp(Infofilename(1:end-9),app.DropDown_2.Value(1:end-4))
                        app.InfoFiletoLoad = Infofilename;
                    end
                end
            end
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            %% When new file is selected and it is a .dat file, update the corresponding Info file (app.InfoFiletoLoad)
            
            app.InfoFiletoLoad = [];

            [stringArray] = Utility_Extract_Contents_of_Folder(app.SelectedFolder);

            Indextodelete = [];
            for i = 1:length(stringArray)
                if ~strcmp(stringArray(i),"")
                    if ~contains(stringArray(i),'.')
                        Indextodelete = [Indextodelete,i];
                    end
                end
            end
            if ~isempty(Indextodelete)
                stringArray(Indextodelete) = [];
            end

            if ~strcmp(app.DropDown_2.Value(end-3:end),".mat")
                for i = 1:length(app.Infofileindex)
                    Infofilename = convertStringsToChars(stringArray(app.Infofileindex(i)));
                    if length(Infofilename) >= 10
                        if strcmp(Infofilename(1:end-9),app.DropDown_2.Value(1:end-4))
                            app.InfoFiletoLoad = Infofilename;
                        end
                    end
                end

            end
        end

        % Close request function: LoadDataWindowUIFigure
        function LoadDataWindowUIFigureCloseRequest(app, event)
            app.Mainapp.LoadDataWindow = [];
            delete(app)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.LoadDataWindowUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {367, 367};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {260, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LoadDataWindowUIFigure = uifigure('Visible', 'off');
            app.LoadDataWindowUIFigure.Name = 'Load Data Window';
            app.LoadDataWindowUIFigure.Icon = 'Logo.png';
            app.LoadDataWindowUIFigure.CloseRequestFcn = createCallbackFcn(app, @LoadDataWindowUIFigureCloseRequest, true);
            app.LoadDataWindowUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.LoadDataWindowUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LoadingOptionsPanel = uipanel(app.LeftPanel);
            app.LoadingOptionsPanel.Title = 'Loading Options';
            app.SelectDifferentFolderButton = uibutton(app.LoadingOptionsPanel, 'push');
            app.SelectDifferentFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDifferentFolderButtonPushed, true);
            app.SelectDifferentFolderButton.Text = {'Select Different '; 'Folder'};
            app.DropDown = uidropdown(app.LoadingOptionsPanel);
            app.DropDown.Items = {};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Enable = 'off';
            app.DropDown.Visible = 'off';
            app.DropDown.Value = {};
            app.DropDown_2 = uidropdown(app.LoadingOptionsPanel);
            app.DropDown_2.Items = {};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = {};
            app.LoadButton = uibutton(app.LoadingOptionsPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SaveFileSelectionLabel = uilabel(app.LoadingOptionsPanel);
            app.SaveFileSelectionLabel.Text = 'Save File Selection';
            app.DataFormatSelectionLabel = uilabel(app.LoadingOptionsPanel);
            app.DataFormatSelectionLabel.Enable = 'off';
            app.DataFormatSelectionLabel.Visible = 'off';
            app.DataFormatSelectionLabel.Text = 'Data Format Selection';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodLabel = uilabel(app.LeftPanel);
            app.NeuromodLabel.Text = 'Neuromod';
            app.LoadDataWindowLabel = uilabel(app.LeftPanel);
            app.LoadDataWindowLabel.Text = 'Load Data Window';
            app.RightPanel = uipanel(app.GridLayout);
            app.TextArea_2 = uitextarea(app.RightPanel);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Enable = 'off';
            app.TextArea_2.Visible = 'off';
            app.CloseButton = uibutton(app.RightPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.TextArea = uitextarea(app.RightPanel);
            app.TextArea.Editable = 'off';
            app.InformationLabel = uilabel(app.RightPanel);
            app.InformationLabel.Text = 'Information';
            % Show the figure after all components are created
            app.LoadDataWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Load_Data_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.LoadDataWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.LoadDataWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LoadDataWindowUIFigure)
        end
    end
end