classdef Extract_Data_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ExtractDataWindowUIFigure    matlab.ui.Figure
        Menu_2                       matlab.ui.container.Menu
        TrajectoryExplorerMenu       matlab.ui.container.Menu
        StartTrajectoryExplorerMenu  matlab.ui.container.Menu
        LoadSavedTrajectoryMenu      matlab.ui.container.Menu
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        ExtractDataWindowLabel       matlab.ui.control.Label
        NeuromodLabel                matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        ExtractDataButton            matlab.ui.control.Button
        ExtractionOptionsPanel       matlab.ui.container.Panel
        AddProbeInformationButton    matlab.ui.control.Button
        FileTypeDropDown             matlab.ui.control.DropDown
        FileTypeLabel                matlab.ui.control.Label
        AutoDetectedRecordingSystemLabel  matlab.ui.control.Label
        RecordingSystemDropDown      matlab.ui.control.DropDown
        SelectDataFolderButton       matlab.ui.control.Button
        AdditionalAmplificationFactorEditField  matlab.ui.control.EditField
        AmplificationFactorLabel     matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        CloseButton                  matlab.ui.control.Button
        FolderContentsLabel          matlab.ui.control.Label
        TextArea_3                   matlab.ui.control.TextArea
        InformationLabel             matlab.ui.control.Label
        TextArea                     matlab.ui.control.TextArea
        TextArea_4                   matlab.ui.control.TextArea
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    % Define app properties shared across all app functions
    properties (Access = public)
        Load_Data_Window_Info = []; % Info structure holding all relevant informations for this window
        ProbeTrajectoryInfo = [];
        ProbeLayoutWindow = [];
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            % Moce this window in the middle of thescreen
            movegui(app.ExtractDataWindowUIFigure, 'center');            %% Predefine variables for the app to work

            app.Load_Data_Window_Info.Mainapp = []; % Save mainapp object containing data. To acccess: app.Load_Data_Window_Info.Mainapp.Data
            app.Load_Data_Window_Info.selectedFolder = []; % Save Data folder user seleceted
            app.Load_Data_Window_Info.FileHeaderPath = []; % Save header file folder user seleceted
            app.Load_Data_Window_Info.Channelorder = []; % Save channel order the user seleceted

            app.Load_Data_Window_Info.Mainapp = Mainapp; % Save Mainapp in this app as object; Data can be accesed via app.Load_Data_Window_Info.Mainapp.Data

            cd(app.Load_Data_Window_Info.Mainapp.executableFolder); % CD in GUI path to make selecting data easier when saved in Raw Data folder

            app.Load_Data_Window_Info.Mainapp.CheckSuccesful_Extraction = 0; % 0 if extraction wasnt succesfully yet

            app.ExtractDataButton.Enable = "on"; % Folder has to be selected for the extraction button to become enabled
            app.TextArea.Value = ["Please select a folder containing your recording data. Supported formats are binary, .nwb and Open Ephys data recordings from Neuropixels 1.0 and 2.0 as well as Intan acquisition boards for the Open Ephys GUI; .dat and .rhd files for the Intan USB Interface Board software; .smrx files for Spike2 and .ncs for Neuralynx files. Folder contents, file types and recording system will be shown automatically if folder contains proper data.";"";"After selecting a folder, specify probe information. Required fields are channel number and channelspacing to specify the probe geometry. Active channel specify the amount and identity of probe channels you recorded from. The number of channels in your recording has to be the same as the number of elements in your active channel selection!";"After setting the probe information you can start the data extraction.";"";"Note: Selected folder is not allowed to contain multiple recordings. For Open Ephys data the standard folder naming scheme with the first folder being named after the recording node has to be preserved!";];
            app.TextArea_3.Value = "Please select a folder";
            pause(0.2);

            %% Dynamically add menu points based on savefile found in standard folder
  
            % Create parent menu
            parentMenu = uimenu(app.ExtractDataWindowUIFigure, 'Text', '   Load Saved Probe Information   ');
            
            % Create the secondary menu 
            filesMenu = uimenu(parentMenu, 'Text', 'Files in Standard Directory');

            % Create the second secondary menu 
            loadMenu = uimenu(parentMenu, 'Text', 'Manually Select File');

            % Extract filenames from standard folder
            DefaultFolder = strcat(app.Load_Data_Window_Info.Mainapp.executableFolder,'\Probe Layouts\Saved Probe Layouts\');

            if ~exist(DefaultFolder,"dir")
                disp(strcat("Standard Folder",DefaultFolder ," was not found. Please load probe information manually. No quick load from menu available!"));
            else
                [stringArray] = Utility_Extract_Contents_of_Folder(DefaultFolder);
                % exclude emoty and non - .mat parts
                stringArray = stringArray(stringArray ~= "");
                fileNames = stringArray(contains(stringArray,".mat"))';

                for i = 1:length(fileNames)
                    uimenu(filesMenu, 'Text', fileNames(i), 'MenuSelectedFcn', @(src,event)Menu_Extract_Data_Callback(app, fileNames(i), DefaultFolder , "Extract Data"));
                end
                
                uimenu(loadMenu, 'Text', 'Manually Select File', 'MenuSelectedFcn', @(src,event)Menu_Extract_Data_Load_Manually_Callback(app, "Extract Data"));
                
            end
        end

        % Button pushed function: ExtractDataButton
        function ExtractDataButtonPushed(app, event)
            if ~isfield(app.Load_Data_Window_Info,'ChannelSpacing')
                msgbox("Error: Please first set a Channelspacing using the 'Probe Information' Button.");
                return;
            else
                if isempty(app.Load_Data_Window_Info.ChannelSpacing)
                    msgbox("Error: Please first set a Channelspacing using the 'Probe Information' Button.");
                    return;
                end
            end
            if isempty(app.Load_Data_Window_Info.selectedFolder)
                msgbox("Error: Please select a folder first.");
                return;
            end

            if ~isfield(app.Load_Data_Window_Info,'NrChannel')
                msgbox("Error: Please first set a channel number using the 'Probe Information' Button.");
                return;
            else
                if isempty(app.Load_Data_Window_Info.NrChannel)
                    msgbox("Error: Please first set a channel number using the 'Probe Information' Button.");
                    return;
                end
            end
            if ~isfield(app.Load_Data_Window_Info,'ActiveChannel')
                msgbox("Error: Please first set active channel using the 'Probe Information' Button.");
                return;
            else
                if isempty(app.Load_Data_Window_Info.NrChannel)
                    msgbox("Error: Please first set active channel using the 'Probe Information' Button.");
                    return;
                end
            end

            app.TextArea_3.Value = "Loading Data, please wait until this window closes.";
            pause(0.01);

            %% Extract Data

            if ~isempty(app.Load_Data_Window_Info.selectedFolder)

                %% Prepare GUI
                % Function to reset Main Window when new Data is loaded
                % i.e resetting plot, TimeRange of plot, channel
                % selection...AND to delete old data structure if already present
                [app.Load_Data_Window_Info.Mainapp] = Organize_Initialize_GUI (app.Load_Data_Window_Info.Mainapp,"Initial");

                %% Extract Data
                [Data,HeaderInfo,SampleRate,RecordingType,Time] = Manage_Dataset_Module_Extract_Raw_Recording_Main(app.RecordingSystemDropDown.Value,app.FileTypeDropDown.Value,app.Load_Data_Window_Info.selectedFolder,app.TextArea,app.Load_Data_Window_Info.Mainapp.executableFolder,app.AdditionalAmplificationFactorEditField.Value,app.Load_Data_Window_Info.NrChannel);
                
                if isempty(Data)
                    app.TextArea.Value = "No Amplifier Channel Data found! Data Extraction cannot be finished.";
                    app.TextArea_3.Value = app.Load_Data_Window_Info.selectedFolder;
                    pause(0.1);
                    return;
                end

                %% Apply/save Header Infos

                % Use some header infos and the data structure to define all necessary variables for this toolbox
                % Variable definitions necessary:
                % app.Data = Data.Raw and or Data.Preprocessed, depending on what is available;
                % app.Data.Time = Time;
                % app.Data.Info = HeaderInfo;
                % app.Data.Info.num_data_points = size(app.Data.Raw,2);
                % app.Data.Info.NrChannel = size(app.Data.Raw,1);
                % app.Data.Info.Data_Path = SelectedFolder;
                % app.Data.Info.NativeSamplingRate = SampleRate;
                % app.Data.Info.RecordingType = RecordingType;
                
                [app.Load_Data_Window_Info.Mainapp] = Organize_Initialize_GUI (app.Load_Data_Window_Info.Mainapp,"VariableDefinition",Data,HeaderInfo,SampleRate,app.Load_Data_Window_Info.selectedFolder,RecordingType,0,Time,app.Load_Data_Window_Info);
    
                %% Apply ChannelOrder

                if ~isempty(app.Load_Data_Window_Info.Channelorder)
                    [app.Load_Data_Window_Info.Mainapp.Data] = Manage_Dataset_Module_Apply_ChannelOrder (app.Load_Data_Window_Info.Mainapp.Data,app.Load_Data_Window_Info.Channelorder);
                end
             
                %% 
            else
                % If the user hasnt selected a folder yet, display that and
                % return
                stringtoshow = "Error. No folder selected";
                f = msgbox(stringtoshow);
                return;
            end

            %% Initialize Infos for Main Window based on extracted and saved variables (previous call of this function)
            [app.Load_Data_Window_Info.Mainapp] = Organize_Initialize_GUI (app.Load_Data_Window_Info.Mainapp,"Extracting");

            %% Get all necessary Infos from Main Window to plot, set time scale based on time window AND plot data
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Load_Data_Window_Info.Mainapp,1,"Initial","Static",app.Load_Data_Window_Info.Mainapp.PlotEvents,app.Load_Data_Window_Info.Mainapp.Plotspikes);
            
            Utility_Initialize_Clicks_Plots(app.Load_Data_Window_Info.Mainapp,"Static");
            
            app.Load_Data_Window_Info.Mainapp.ProbeViewWindowHandle = Probe_View_Window(app.Load_Data_Window_Info.Mainapp,'MainWindow');

            [app.Load_Data_Window_Info.Mainapp] = Utility_Set_ToolTips(app.Load_Data_Window_Info.Mainapp,app.Load_Data_Window_Info.Mainapp.ShowToolTipsSetting,"ProbeViewMainWindow");

            % Close this window when Extraction succesfull
            close(app.ExtractDataWindowUIFigure);

        end

        % Button pushed function: SelectDataFolderButton
        function SelectDataFolderButtonPushed(app, event)
            
            cd(strcat(app.Load_Data_Window_Info.Mainapp.executableFolder,'\Recording Data\Raw Recordings\'));

            [Formatsfound,TextAreaText,TextArea_3Text,RecordingSystemDropDownItems,FileTypeDropDownItems,stringArray,SelectedFolder] = Manage_Dataset_Module_LoadRecordingCheckFolderContents(app.Load_Data_Window_Info);

            app.Load_Data_Window_Info.selectedFolder = SelectedFolder;

            if isempty(FileTypeDropDownItems) || isempty(RecordingSystemDropDownItems)
                app.TextArea_3.Value = "Folder contains recordings of multiple recording systems. Please select a folder with only a single recording of a single recording system.";
                return;
            else
                app.FileTypeDropDown.Items = FileTypeDropDownItems;
                app.RecordingSystemDropDown.Items = RecordingSystemDropDownItems;
            end

            if ~isempty(TextAreaText)
                    app.TextArea_3.Value = TextAreaText;
            else
                if ~isempty(stringArray)
                    if strcmp(RecordingSystemDropDownItems{1},'Open Ephys')
                        for i = 1:length(stringArray)
                            if strcmp(stringArray(i),"Record Node 101")
                                stringArray(i) = strcat(stringArray(i));
                            elseif strcmp(stringArray(i),"Record Node 105")
                                stringArray(i) = strcat(stringArray(i));
                            elseif strcmp(stringArray(i),"Record Node 113")
                                stringArray(i) = strcat(stringArray(i));
                            end
                        end
                        stringArray = ["Selected folder contains the following recording files:";RecordingSystemDropDownItems{1};"";stringArray];
                        app.TextArea_3.Value = stringArray;
                    else
                        stringArray = ["Selected folder contains the following recording files:";RecordingSystemDropDownItems{1};"";stringArray];
                        app.TextArea_3.Value = stringArray;
                    end

                    app.ExtractDataButton.Enable = "on";                   
                end
            end

            if ~isempty(TextArea_3Text)
                app.TextArea.Value = TextArea_3Text; 
            end

            if isempty(Formatsfound)
                app.ExtractDataButton.Enable = "off";
            end
        end

        % Button pushed function: AddProbeInformationButton
        function AddProbeInformationButtonPushed(app, event)
            
            app.ProbeLayoutWindow = Probe_Layout_Window(app.Load_Data_Window_Info.Mainapp,app);
            
            [app.Load_Data_Window_Info.Mainapp] = Utility_Set_ToolTips(app.Load_Data_Window_Info.Mainapp,app.Load_Data_Window_Info.Mainapp.ShowToolTipsSetting,"SetProbeInfoWindow");


        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            if ~isempty(app.ProbeLayoutWindow)
                delete(app.ProbeLayoutWindow);
            end

            app.Load_Data_Window_Info.Mainapp.ExtractDataWindow = [];

            delete(app);
        end

        % Value changed function: RecordingSystemDropDown
        function RecordingSystemDropDownValueChanged(app, event)
          
        end

        % Value changed function: FileTypeDropDown
        function FileTypeDropDownValueChanged(app, event)
           
            
        end

        % Value changed function: AdditionalAmplificationFactorEditField
        function AdditionalAmplificationFactorEditFieldValueChanged(app, event)
            
        end

        % Menu selected function: StartTrajectoryExplorerMenu
        function StartTrajectoryExplorerMenuSelected(app, event)
            neuropixels_trajectory_explorer;
        end

        % Menu selected function: LoadSavedTrajectoryMenu
        function LoadSavedTrajectoryMenuSelected(app, event)
            cd(strcat(app.Load_Data_Window_Info.Mainapp.executableFolder,'\Probe Layouts\Trajectories\'));

            [fileName, filePath] = uigetfile('*.mat*', 'Select a File to Load');
            
            % Check if the user selected a file or canceled the dialog
            if isequal(fileName, 0)
                disp('File selection canceled.');
            else
               
                fullFileName = fullfile(filePath, fileName);

                % Load the file (assuming it's a .mat file)
                LoadedProbeTrajectoryInfo = load(fullFileName);
                Areas = LoadedProbeTrajectoryInfo.probe_areas;

                app.Load_Data_Window_Info.ProbeTrajectoryInfo.AreaNamesLong = Areas{1}.name;
                app.Load_Data_Window_Info.ProbeTrajectoryInfo.AreaNamesShort = Areas{1}.acronym;
                
                app.Load_Data_Window_Info.ProbeTrajectoryInfo.AreaTipDistance = Areas{1}.tip_distance;
                
                disp('Probe Trajectory Info successfully loaded.');
                
            end

        end

        % Close request function: ExtractDataWindowUIFigure
        function ExtractDataWindowUIFigureCloseRequest(app, event)
            if ~isempty(app.ProbeLayoutWindow)
                delete(app.ProbeLayoutWindow);
            end

            app.Load_Data_Window_Info.Mainapp.ExtractDataWindow = [];

            delete(app);
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ExtractDataWindowUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {520, 520};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {251, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ExtractDataWindowUIFigure = uifigure('Visible', 'off');
            app.ExtractDataWindowUIFigure.Name = 'Extract Data Window';
            app.ExtractDataWindowUIFigure.Icon = 'Logo.png';
            app.ExtractDataWindowUIFigure.CloseRequestFcn = createCallbackFcn(app, @ExtractDataWindowUIFigureCloseRequest, true);
            app.ExtractDataWindowUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.Menu_2 = uimenu(app.ExtractDataWindowUIFigure);
            app.TrajectoryExplorerMenu = uimenu(app.ExtractDataWindowUIFigure);
            app.TrajectoryExplorerMenu.Text = '   Trajectory Explorer   ';
            app.StartTrajectoryExplorerMenu = uimenu(app.TrajectoryExplorerMenu);
            app.StartTrajectoryExplorerMenu.MenuSelectedFcn = createCallbackFcn(app, @StartTrajectoryExplorerMenuSelected, true);
            app.StartTrajectoryExplorerMenu.Text = 'Start Trajectory Explorer (requires to download Allen mouse brain atlas)';
            app.LoadSavedTrajectoryMenu = uimenu(app.TrajectoryExplorerMenu);
            app.LoadSavedTrajectoryMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadSavedTrajectoryMenuSelected, true);
            app.LoadSavedTrajectoryMenu.Text = 'Load Saved Trajectory';
            app.GridLayout = uigridlayout(app.ExtractDataWindowUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ExtractionOptionsPanel = uipanel(app.LeftPanel);
            app.ExtractionOptionsPanel.Title = 'Extraction Options';
            app.AmplificationFactorLabel = uilabel(app.ExtractionOptionsPanel);
            app.AmplificationFactorLabel.Text = {'Additional'; 'Amplification Factor:'};
            app.AdditionalAmplificationFactorEditField = uieditfield(app.ExtractionOptionsPanel, 'text');
            app.AdditionalAmplificationFactorEditField.ValueChangedFcn = createCallbackFcn(app, @AdditionalAmplificationFactorEditFieldValueChanged, true);
            app.SelectDataFolderButton = uibutton(app.ExtractionOptionsPanel, 'push');
            app.SelectDataFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDataFolderButtonPushed, true);
            app.SelectDataFolderButton.Text = 'Select Data Folder';
            app.RecordingSystemDropDown = uidropdown(app.ExtractionOptionsPanel);
            app.RecordingSystemDropDown.Items = {};
            app.RecordingSystemDropDown.ValueChangedFcn = createCallbackFcn(app, @RecordingSystemDropDownValueChanged, true);
            app.RecordingSystemDropDown.Value = {};
            app.AutoDetectedRecordingSystemLabel = uilabel(app.ExtractionOptionsPanel);
            app.AutoDetectedRecordingSystemLabel.Text = {'Auto Detected'; 'Recording System'};
            app.FileTypeLabel = uilabel(app.ExtractionOptionsPanel);
            app.FileTypeLabel.Text = 'File Type';
            app.FileTypeDropDown = uidropdown(app.ExtractionOptionsPanel);
            app.FileTypeDropDown.Items = {};
            app.FileTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @FileTypeDropDownValueChanged, true);
            app.FileTypeDropDown.Value = {};
            app.AddProbeInformationButton = uibutton(app.ExtractionOptionsPanel, 'push');
            app.AddProbeInformationButton.ButtonPushedFcn = createCallbackFcn(app, @AddProbeInformationButtonPushed, true);
            app.AddProbeInformationButton.Text = {'Add Probe'; 'Information'};
            app.ExtractDataButton = uibutton(app.LeftPanel, 'push');
            app.ExtractDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractDataButtonPushed, true);
            app.ExtractDataButton.Text = 'Extract Data';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodLabel = uilabel(app.LeftPanel);
            app.NeuromodLabel.Text = 'Neuromod ';
            app.ExtractDataWindowLabel = uilabel(app.LeftPanel);
            app.ExtractDataWindowLabel.Text = 'Extract Data Window';
            app.RightPanel = uipanel(app.GridLayout);
            app.TextArea_4 = uitextarea(app.RightPanel);
            app.TextArea_4.Editable = 'off';
            app.TextArea_4.Enable = 'off';
            app.TextArea_4.Visible = 'off';
            app.TextArea = uitextarea(app.RightPanel);
            app.TextArea.Editable = 'off';
            app.InformationLabel = uilabel(app.RightPanel);
            app.InformationLabel.Text = 'Information';
            app.TextArea_3 = uitextarea(app.RightPanel);
            app.TextArea_3.Editable = 'off';
            app.FolderContentsLabel = uilabel(app.RightPanel);
            app.FolderContentsLabel.Text = 'Folder Contents';
            app.CloseButton = uibutton(app.RightPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.ExtractDataWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Extract_Data_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ExtractDataWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ExtractDataWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ExtractDataWindowUIFigure)
        end
    end
end