classdef OE_Multiple_Recording_Selection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SelectRecordingWindowUIFigure  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        RightPanel                     matlab.ui.container.Panel
        TextArea_3                     matlab.ui.control.TextArea
        TextArea_2                     matlab.ui.control.TextArea
        ProceedwithSelectedRecordingsButton  matlab.ui.control.Button
        RecordingstoselectEditField    matlab.ui.control.EditField
        RecordingstoselectEditFieldLabel  matlab.ui.control.Label
        TextArea                       matlab.ui.control.TextArea
        RecordingSelectionWindowLabel  matlab.ui.control.Label
        NeuromodLabel                  matlab.ui.control.Label
        Image                          matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        NumsRecordings = [];
        SelectedRecordings = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, RecordingInfo)
            % Moce this window in the middle of thescreen
            movegui(app.SelectRecordingWindowUIFigure, 'center');            %% Predefine variables for the app to work

            app.NumsRecordings = RecordingInfo;

            TexttoShow = strcat("Found ",num2str(app.NumsRecordings)," recordings within the same data aquisition session. You now have the option to extract data for only a selected number of those recordings or all of them. Modify the editfield to the right for that purpose. ");

            TexttoShow = [TexttoShow;"";"All recording indicies seperated by a comma are extracted together as part of the same dataset. Recording indicies deleted from that list will not be extracted."];

            app.TextArea.Value = TexttoShow;

            texttoshow = [];
            for i = 1:app.NumsRecordings
                if i == app.NumsRecordings
                    texttoshow = strcat(texttoshow,num2str(i));
                else
                    texttoshow = strcat(texttoshow,num2str(i),",");
                end
            end

            app.RecordingstoselectEditField.Value = texttoshow;
        end

        % Button pushed function: ProceedwithSelectedRecordingsButton
        function ProceedwithSelectedRecordingsButtonPushed(app, event)
            app.SelectedRecordings = str2double(strsplit(app.RecordingstoselectEditField.Value,','));

            uiresume(app.SelectRecordingWindowUIFigure);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SelectRecordingWindowUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {305, 305};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SelectRecordingWindowUIFigure = uifigure('Visible', 'off');
            app.SelectRecordingWindowUIFigure.Name = 'Select Recording Window';
            app.SelectRecordingWindowUIFigure.Icon = 'Logo.png';
            app.SelectRecordingWindowUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.SelectRecordingWindowUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodLabel = uilabel(app.RightPanel);
            app.NeuromodLabel.Text = 'Neuromod ';
            app.RecordingSelectionWindowLabel = uilabel(app.RightPanel);
            app.RecordingSelectionWindowLabel.Text = 'Recording Selection Window';
            app.TextArea = uitextarea(app.RightPanel);
            app.RecordingstoselectEditFieldLabel = uilabel(app.RightPanel);
            app.RecordingstoselectEditFieldLabel.Text = 'Recordings to select:';
            app.RecordingstoselectEditField = uieditfield(app.RightPanel, 'text');
            app.ProceedwithSelectedRecordingsButton = uibutton(app.RightPanel, 'push');
            app.ProceedwithSelectedRecordingsButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedwithSelectedRecordingsButtonPushed, true);
            app.ProceedwithSelectedRecordingsButton.Text = {'Proceed with '; 'Selected Recordings'};
            app.TextArea_2 = uitextarea(app.RightPanel);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Enable = 'off';
            app.TextArea_2.Visible = 'off';
            app.TextArea_3 = uitextarea(app.RightPanel);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Enable = 'off';
            app.TextArea_3.Visible = 'off';
            % Show the figure after all components are created
            app.SelectRecordingWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OE_Multiple_Recording_Selection(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SelectRecordingWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.SelectRecordingWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SelectRecordingWindowUIFigure)
        end
    end
end