classdef Probe_Layout_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ProbeLayoutWindowUIFigure      matlab.ui.Figure
        SaveProbeLayoutMenu            matlab.ui.container.Menu
        SaveProbeInfoforKilosortMenu   matlab.ui.container.Menu
        SaveProbeInfoforGUIMenu        matlab.ui.container.Menu
        SaveChannelOrderMenu           matlab.ui.container.Menu
        SaveActiveChannelMenu          matlab.ui.container.Menu
        TrajectoryExplorerMenu         matlab.ui.container.Menu
        Menu                           matlab.ui.container.Menu
        LoadSavedTrajectoryMenu        matlab.ui.container.Menu
        ShowChannelSpacingCheckBox     matlab.ui.control.CheckBox
        SetProbeInformationandContinueButton  matlab.ui.control.Button
        NeuromodLabel                  matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        ProbeLayoutWindowLabel         matlab.ui.control.Label
        SetProbeGeometryPanel          matlab.ui.container.Panel
        CheckBox                       matlab.ui.control.CheckBox
        VerticalOffsetumEditField_2    matlab.ui.control.EditField
        VerticalOffsetBetweenChannelRowsumLabel_2  matlab.ui.control.Label
        HorizontalOffsetumEditField    matlab.ui.control.EditField
        HorizontalOffsetBetweenChannelRowsmLabel  matlab.ui.control.Label
        NrChannelEditField             matlab.ui.control.EditField
        NrChannelperRowEditFieldLabel  matlab.ui.control.Label
        VerticalOffsetumEditField      matlab.ui.control.EditField
        VerticalOffsetBetweenChannelRowsumLabel  matlab.ui.control.Label
        ChannelRowsDropDown            matlab.ui.control.DropDown
        ChannelRowsDropDownLabel       matlab.ui.control.Label
        ChannelSpacingumEditField      matlab.ui.control.EditField
        ChannelSpacingumLabel          matlab.ui.control.Label
        CloseButton                    matlab.ui.control.Button
        InteractiveProbeViewLabel      matlab.ui.control.Label
        SetChannelInformationPanel     matlab.ui.container.Panel
        ActiveChannelLabel_2           matlab.ui.control.Label
        ChannelOrderjustforactiveChannelLabel  matlab.ui.control.Label
        LoadChannelOrderButton         matlab.ui.control.Button
        LoadActiveChannelSelectionButton  matlab.ui.control.Button
        ActiveChannelField             matlab.ui.control.TextArea
        ChannelOrderFormat1234LeaveemptyfornocostumorderLabel_2  matlab.ui.control.Label
        ChannelOrderField              matlab.ui.control.TextArea
        ChannelOrderFormat1234LeaveemptyfornocostumorderLabel  matlab.ui.control.Label
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Mainapp = []; % Description
        ExtractRecordingWindow = [];
        FirstZoomChannel = 1;
        ProbeTrajectoryInfo = [];

        ProbeInfoandPath = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp, ExtractRecordingWindow)
            % Moce this window in the middle of thescreen
            movegui(app.ProbeLayoutWindowUIFigure, 'center');

            app.Mainapp = Mainapp;
            app.ExtractRecordingWindow = ExtractRecordingWindow;

            % Clear any previous plots and hold the plot for multiple elements
            cla(app.UIAxes);
            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";
            % Turn off the box around app.UIAxes
            box(app.UIAxes, 'off');
            app.UIAxes.FontSize = 10;
            %yTicks = get(Figure, 'YTickLabel'); % Current y-tick values
            set(app.UIAxes, 'XTickLabel',[]);

            %% Dynamically add menu points based on savefile found in standard folder
  
            % Create parent menu
            parentMenu = uimenu(app.ProbeLayoutWindowUIFigure, 'Text', '   Load Saved Probe Information   ');
            
            % Create the secondary menu 
            filesMenu = uimenu(parentMenu, 'Text', 'Files in Standard Directory');

            % Create the second secondary menu 
            loadMenu = uimenu(parentMenu, 'Text', 'Manually Select File');

            % Extract filenames from standard folder
            DefaultFolder = strcat(app.Mainapp.executableFolder,'\Probe Layouts\Saved Probe Layouts\');

            if ~exist(DefaultFolder,"dir")
                disp(strcat("Standard Folder",DefaultFolder ," was not found. Please load probe information manually. No quick load from menu available!"));
            else
                [stringArray] = Utility_Extract_Contents_of_Folder(DefaultFolder);
                % exclude emoty and non - .mat parts
                stringArray = stringArray(stringArray ~= "");
                fileNames = stringArray(contains(stringArray,".mat"))';

                for i = 1:length(fileNames)
                    uimenu(filesMenu, 'Text', fileNames(i), 'MenuSelectedFcn', @(src,event)Menu_Extract_Data_Callback(app, fileNames(i), DefaultFolder, "ProbeLayout"));
                end
                
                uimenu(loadMenu, 'Text', 'Manually Select File', 'MenuSelectedFcn', @(src,event)Menu_Extract_Data_Load_Manually_Callback(app, "ProbeLayout"));
                
            end
        end

        % Button pushed function: LoadChannelOrderButton
        function LoadChannelOrderButtonPushed(app, event)
            cd(strcat(app.ExtractRecordingWindow.Mainapp.executableFolder,'\Probe Layouts\Channel Orders\'));
            %% Load a .mat file containing a vector with numbers indicating the true position of a channel on the probe
            % See ReadMe file in the ChannelOrder folder of the GUI 
            % stringtoshow = "Please select a .mat file containing your channel order (numbers).";
            % f = msgbox(stringtoshow);
            % Use uiget to to select a .mat file
            [file, path] = uigetfile('*.mat', 'Select a .mat file');
            
            % Check if the user cancels the operation
            if isequal(file,0) || isequal(path,0)
                disp('Operation canceled.');
                return;
            end
            
            % Load the selected .mat file and save in the mainapp
            tempChOrder = load(fullfile(path, file));
            % Get the field names of the structure
            fieldNames = fieldnames(tempChOrder);
            fieldName = fieldNames{1};

            app.ProbeInfoandPath.Channelorder = tempChOrder.(fieldName);

            %% Display the channelorder in the Window 

            texttoshow = sprintf('%d, ', app.ProbeInfoandPath.Channelorder);
            % Remove the trailing comma and whitespace
            texttoshow(end-1:end) = [];

            app.ChannelOrderField.Value = texttoshow;

        end

        % Value changed function: ChannelSpacingumEditField
        function ChannelSpacingumEditFieldValueChanged(app, event)
            
           
            if ~isempty(app.NrChannelEditField.Value) && ~isempty(app.ChannelSpacingumEditField.Value)

                if str2double(app.ChannelRowsDropDown.Value) == 1
                    if str2double(app.NrChannelEditField.Value) >=32
                        app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-31;
                    else
                        app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-(str2double(app.NrChannelEditField.Value)-1);
                    end 

                    if isempty(app.ActiveChannelField.Value{1})
                        ActiveChannel = 1:str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value);
                    else
                        ActiveChannel = str2double(strsplit(app.ActiveChannelField.Value{1},','));
                    end
                    
                    if isfield(app.ProbeTrajectoryInfo,'AreaNamesLong')
                            BrainAreaInfo = app.ProbeTrajectoryInfo;
                        else
                            BrainAreaInfo = [];
                    end

                    Utility_Plot_Interactive_Probe_View(app.UIAxes,str2double(app.ChannelSpacingumEditField.Value),str2double(app.NrChannelEditField.Value),str2double(app.ChannelRowsDropDown.Value),str2double(app.HorizontalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField.Value),app.ChannelOrderField.Value,ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,ActiveChannel,app.ShowChannelSpacingCheckBox.Value,1,1,[],app.CheckBox.Value,[])
                else
                    if str2double(app.NrChannelEditField.Value)*2 >=64
                        app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-31;
                    else
                        app.FirstZoomChannel = (str2double(app.NrChannelEditField.Value)-1)-(str2double(app.NrChannelEditField.Value));
                    end 

                    if isempty(app.ActiveChannelField.Value{1})
                        ActiveChannel = 1:str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value);
                    else
                        ActiveChannel = str2double(strsplit(app.ActiveChannelField.Value{1},','));
                    end

                    if isfield(app.ProbeTrajectoryInfo,'AreaNamesLong')
                        BrainAreaInfo = app.ProbeTrajectoryInfo;
                    else
                        BrainAreaInfo = [];
                    end

                    %app.FirstZoomChannel = str2double(app.NrChannelEditField.Value);
                    Utility_Plot_Interactive_Probe_View(app.UIAxes,str2double(app.ChannelSpacingumEditField.Value),str2double(app.NrChannelEditField.Value),str2double(app.ChannelRowsDropDown.Value),str2double(app.HorizontalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField.Value),app.ChannelOrderField.Value,ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,ActiveChannel,app.ShowChannelSpacingCheckBox.Value,1,1,[],app.CheckBox.Value,[])
                end


                %% Initiate Callback
                if isempty(app.UIAxes.ButtonDownFcn)
                    app.UIAxes.ButtonDownFcn = @(src1, event1) ProbeViewClickCallback(app, event1);
                end    
            
                % Add ButtonDownFcn to each line object in UIAxis
                lines = findobj(app.UIAxes, 'Type', 'line');
                
                % Add ButtonDownFcn to each line object in UIAxis
                ChannelViewRight = findobj(app.UIAxes,'Tag','ChannelViewRight');
            
                % Add ButtonDownFcn to each line object in UIAxis
                ChannelViewLeft = findobj(app.UIAxes,'Tag','ChannelViewLeft');
            
                % Add ButtonDownFcn to each line object in UIAxis
                GrayProbeFilling = findobj(app.UIAxes,'Tag','GrayProbeFilling');
                    
                %% Set the ButtonDownFcn for UIAxes to register clicks on a plotted line directly
                % lines plotted
                for i = 1:numel(lines)
                    % Call Lineclicked function if that happens
                    lines(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
            
                % Channel squares on the right
                for i = 1:numel(ChannelViewRight)
                    % Call Lineclicked function if that happens
                    ChannelViewRight(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                % Channel squares on the left
                for i = 1:numel(ChannelViewLeft)
                    % Call Lineclicked function if that happens
                    ChannelViewLeft(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                % Channel squares on the left
                for i = 1:numel(GrayProbeFilling)
                    % Call Lineclicked function if that happens
                    GrayProbeFilling(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end

                %% Create Legend
                % Create a dummy red line plot for the legend
                dummyLine = plot(app.UIAxes2, NaN, NaN, 'Color', 'red', 'LineWidth', 2);
                
                % Create a dummy yellow rectangle using a patch for the legend
                dummyRect = patch(app.UIAxes2, NaN, NaN, 'yellow', 'EdgeColor', 'none');
                
                % Create the legend and position it at the center of the plot
                legend(app.UIAxes2, [dummyLine, dummyRect], {'All Active Channel', 'Currently Active Channel'}, 'AutoUpdate', 'off');
                
                % Position the legend in the middle of the figure (over the entire figure size)
                legendPosition = [0.6, 0.9, 0.15, 0.04]; % x, y, width, height (normalized)
                set(app.UIAxes2.Legend, 'Position', legendPosition);
                
            end
        end

        % Button pushed function: SetProbeInformationandContinueButton
        function SetProbeInformationandContinueButtonPushed(app, event)
            [app.ExtractRecordingWindow.ProbeInfoandPath,ProbeInfoText,~] = Manage_Dataset_Module_Show_ProbeInfo_and_Path(app,app.ExtractRecordingWindow.ProbeInfoandPath,"ProbeInfo");

            if isprop(app.ExtractRecordingWindow,'AutorunManagerWindowUIFigure')
                app.ExtractRecordingWindow.TextArea_2.Value = ProbeInfoText;
            else
                app.ExtractRecordingWindow.TextArea.Value = ProbeInfoText;
            end

            delete(app);
        end

        % Callback function
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Callback function
        function SaveChannelmapasmatforKilosortButtonPushed(app, event)
            cd(app.Mainapp.executableFolder);

            if isempty(app.ChannelSpacingumEditField.Value)
                stringtoshow = "Error. Please first define a channel spacing on the left side of this window";
                msgbox(stringtoshow);
                return;
            end
            if isempty(app.ChannelmapNameEditField.Value)
                stringtoshow = "Error. Please first define a channel name on the right side of this window";
                msgbox(stringtoshow);
                return;
            else
                name = app.ChannelmapNameEditField.Value;
            end

            if isempty(app.NrofProbeChannelEditField.Value)
                stringtoshow = "Error. Please first define a channel number on the right side of this window";
                msgbox(stringtoshow);
                return;
            end
            
            NrChannel = str2double(app.NrofProbeChannelEditField.Value);

            xcoords = zeros(NrChannel,1);
            ycoords = 0:str2double(app.ChannelSpacingumEditField.Value):str2double(app.ChannelSpacingumEditField.Value)*NrChannel-str2double(app.ChannelSpacingumEditField.Value);

            ycoords = ycoords';
            kcoords = zeros(NrChannel,1)+1;
            connected = true(NrChannel,1);
            chanMap = 1:1:NrChannel;
            chanMap = chanMap';
            chanMap0ind = 0:1:NrChannel-1;
            chanMap0ind = chanMap0ind';

            % Prompt user for file save location and name
            [file, path] = uiputfile('*.mat', 'Save as');
            
            if isequal(file,0) || isequal(path,0)
                disp('User canceled the operation.');
                return;
            end
    
            % Construct the full file path
            filepath = fullfile(path, file);
            % Save the variables to the .mat file
            save(filepath, 'xcoords','ycoords','kcoords','chanMap','chanMap0ind','connected');
            stringtoshow = ['Channelmap saved to: ' filepath];
            msgbox(stringtoshow);
        end

        % Value changed function: NrChannelEditField
        function NrChannelEditFieldValueChanged(app, event)
          
            if ~isempty(app.NrChannelEditField.Value) && ~isempty(app.ChannelSpacingumEditField.Value)

                if str2double(app.ChannelRowsDropDown.Value) == 1
                    if str2double(app.NrChannelEditField.Value) >=32
                        app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-31;
                    else
                        app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-(str2double(app.NrChannelEditField.Value)-1);
                    end 

                    if isempty(app.ActiveChannelField.Value{1})
                        ActiveChannel = 1:str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value);
                    else
                        ActiveChannel = str2double(strsplit(app.ActiveChannelField.Value{1},','));
                    end

                    if isfield(app.ProbeTrajectoryInfo,'AreaNamesLong')
                        BrainAreaInfo = app.ProbeTrajectoryInfo;
                    else
                        BrainAreaInfo = [];
                    end

                    Utility_Plot_Interactive_Probe_View(app.UIAxes,str2double(app.ChannelSpacingumEditField.Value),str2double(app.NrChannelEditField.Value),str2double(app.ChannelRowsDropDown.Value),str2double(app.HorizontalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField.Value),app.ChannelOrderField.Value,ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,ActiveChannel,app.ShowChannelSpacingCheckBox.Value,1,1,[],app.CheckBox.Value,[])
                else
                    if str2double(app.NrChannelEditField.Value)*2 >=64
                        app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-31;
                    else
                        app.FirstZoomChannel = (str2double(app.NrChannelEditField.Value)-1)-(str2double(app.NrChannelEditField.Value));
                    end 

                    if isempty(app.ActiveChannelField.Value{1})
                        ActiveChannel = 1:str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value);
                    else
                        ActiveChannel = str2double(strsplit(app.ActiveChannelField.Value{1},','));
                    end

                    if isfield(app.ProbeTrajectoryInfo,'AreaNamesLong')
                        BrainAreaInfo = app.ProbeTrajectoryInfo;
                    else
                        BrainAreaInfo = [];
                    end

                    %app.FirstZoomChannel = str2double(app.NrChannelEditField.Value);
                    Utility_Plot_Interactive_Probe_View(app.UIAxes,str2double(app.ChannelSpacingumEditField.Value),str2double(app.NrChannelEditField.Value),str2double(app.ChannelRowsDropDown.Value),str2double(app.HorizontalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField.Value),app.ChannelOrderField.Value,ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,ActiveChannel,app.ShowChannelSpacingCheckBox.Value,1,1,[],app.CheckBox.Value,[])
                end


                %% Initiate Callback
                if isempty(app.UIAxes.ButtonDownFcn)
                    app.UIAxes.ButtonDownFcn = @(src1, event1) ProbeViewClickCallback(app, event1);
                end    
            
                % Add ButtonDownFcn to each line object in UIAxis
                lines = findobj(app.UIAxes, 'Type', 'line');
                
                % Add ButtonDownFcn to each line object in UIAxis
                ChannelViewRight = findobj(app.UIAxes,'Tag','ChannelViewRight');
            
                % Add ButtonDownFcn to each line object in UIAxis
                ChannelViewLeft = findobj(app.UIAxes,'Tag','ChannelViewLeft');
            
                % Add ButtonDownFcn to each line object in UIAxis
                GrayProbeFilling = findobj(app.UIAxes,'Tag','GrayProbeFilling');
                    
                %% Set the ButtonDownFcn for UIAxes to register clicks on a plotted line directly
                % lines plotted
                for i = 1:numel(lines)
                    % Call Lineclicked function if that happens
                    lines(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
            
                % Channel squares on the right
                for i = 1:numel(ChannelViewRight)
                    % Call Lineclicked function if that happens
                    ChannelViewRight(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                % Channel squares on the left
                for i = 1:numel(ChannelViewLeft)
                    % Call Lineclicked function if that happens
                    ChannelViewLeft(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                % Channel squares on the left
                for i = 1:numel(GrayProbeFilling)
                    % Call Lineclicked function if that happens
                    GrayProbeFilling(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end

                %% Add Max Value to yaxis labels so that 0 is up when yticks are flipped
            % % Get the current YLim and YTick values
            % yLimits = app.UIAxes.YLim;
            % currentYTicks = app.UIAxes.YTick;
            % currentYTickLabels = app.UIAxes.YTickLabel;
            % 
            % % Check if the maximum YLim value is already included in YTicks
            % if ~ismember(yLimits(2), currentYTicks)
            %     % If not, add the max Y value to the YTicks
            %     updatedYTicks = [currentYTicks, yLimits(2)];  % Append max Y value
            %     updatedYTickLabels = [currentYTickLabels; string(yLimits(2))];  % Append label for max Y value
            % 
            %     % Set the updated ticks and labels
            %     app.UIAxes.YTick = updatedYTicks;
            %     app.UIAxes.YTickLabel = updatedYTickLabels;
            % end
            % 
            % %% Flip yaxis
            % 
            % % Get current y-axis ticks and labels from the UIAxes object
            % currentYTicks = app.UIAxes.YTick;
            % currentYTickLabels = app.UIAxes.YTickLabel;
            % 
            % % Reverse the order of the labels
            % reversedYTickLabels = flip(currentYTickLabels);
            % 
            % % Set the new reversed labels to the y-axis
            % app.UIAxes.YTickLabel = reversedYTickLabels;

            %% Create Legend
            % Create a dummy red line plot for the legend
            dummyLine = plot(app.UIAxes2, NaN, NaN, 'Color', 'red', 'LineWidth', 2);
            
            % Create a dummy yellow rectangle using a patch for the legend
            dummyRect = patch(app.UIAxes2, NaN, NaN, 'yellow', 'EdgeColor', 'none');
            
            % Create the legend and position it at the center of the plot
            legend(app.UIAxes2, [dummyLine, dummyRect], {'All Active Channel', 'Currently Active Channel'}, 'AutoUpdate', 'off');
            
            % Position the legend in the middle of the figure (over the entire figure size)
            legendPosition = [0.6, 0.9, 0.15, 0.04]; % x, y, width, height (normalized)
            set(app.UIAxes2.Legend, 'Position', legendPosition);
                
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed2(app, event)
            app.ExtractRecordingWindow.ProbeLayoutWindow = [];
            delete(app)
        end

        % Value changed function: HorizontalOffsetumEditField
        function HorizontalOffsetumEditFieldValueChanged(app, event)
            
        end

        % Value changed function: ChannelRowsDropDown
        function ChannelRowsDropDownValueChanged(app, event)
           
            if str2double(app.ChannelRowsDropDown.Value) == 1
                if str2double(app.NrChannelEditField.Value) >=32
                    app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-31;
                else
                    app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-(str2double(app.NrChannelEditField.Value)-1);
                end 

                if isempty(app.ActiveChannelField.Value{1})
                    ActiveChannel = 1:str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value);
                else
                    ActiveChannel = str2double(strsplit(app.ActiveChannelField.Value{1},','));
                end

                if isfield(app.ProbeTrajectoryInfo,'AreaNamesLong')
                        BrainAreaInfo = app.ProbeTrajectoryInfo;
                    else
                        BrainAreaInfo = [];
                end

                Utility_Plot_Interactive_Probe_View(app.UIAxes,str2double(app.ChannelSpacingumEditField.Value),str2double(app.NrChannelEditField.Value),str2double(app.ChannelRowsDropDown.Value),str2double(app.HorizontalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField.Value),app.ChannelOrderField.Value,ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,ActiveChannel,app.ShowChannelSpacingCheckBox.Value,1,1,[],app.CheckBox.Value,[])
            else
                if str2double(app.NrChannelEditField.Value)*2 >=64
                    app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-31;
                else
                    app.FirstZoomChannel = (str2double(app.NrChannelEditField.Value)-1)-(str2double(app.NrChannelEditField.Value));
                end 

                if isempty(app.ActiveChannelField.Value{1})
                    ActiveChannel = 1:str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value);
                else
                    ActiveChannel = str2double(strsplit(app.ActiveChannelField.Value{1},','));
                end

                if isfield(app.ProbeTrajectoryInfo,'AreaNamesLong')
                        BrainAreaInfo = app.ProbeTrajectoryInfo;
                    else
                        BrainAreaInfo = [];
                end

                %app.FirstZoomChannel = str2double(app.NrChannelEditField.Value);
                Utility_Plot_Interactive_Probe_View(app.UIAxes,str2double(app.ChannelSpacingumEditField.Value),str2double(app.NrChannelEditField.Value),str2double(app.ChannelRowsDropDown.Value),str2double(app.HorizontalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField.Value),app.ChannelOrderField.Value,ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,ActiveChannel,app.ShowChannelSpacingCheckBox.Value,1,1,[],app.CheckBox.Value,[])
            end

            if ~isempty(app.NrChannelEditField.Value) && ~isempty(app.ChannelSpacingumEditField.Value)
                %% Initiate Callback
                if isempty(app.UIAxes.ButtonDownFcn)
                    app.UIAxes.ButtonDownFcn = @(src1, event1) ProbeViewClickCallback(app, event1);
                end    
            
                % Add ButtonDownFcn to each line object in UIAxis
                lines = findobj(app.UIAxes, 'Type', 'line');
                
                % Add ButtonDownFcn to each line object in UIAxis
                ChannelViewRight = findobj(app.UIAxes,'Tag','ChannelViewRight');
            
                % Add ButtonDownFcn to each line object in UIAxis
                ChannelViewLeft = findobj(app.UIAxes,'Tag','ChannelViewLeft');
            
                % Add ButtonDownFcn to each line object in UIAxis
                GrayProbeFilling = findobj(app.UIAxes,'Tag','GrayProbeFilling');
                    
                %% Set the ButtonDownFcn for UIAxes to register clicks on a plotted line directly
                % lines plotted
                for i = 1:numel(lines)
                    % Call Lineclicked function if that happens
                    lines(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
            
                % Channel squares on the right
                for i = 1:numel(ChannelViewRight)
                    % Call Lineclicked function if that happens
                    ChannelViewRight(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                % Channel squares on the left
                for i = 1:numel(ChannelViewLeft)
                    % Call Lineclicked function if that happens
                    ChannelViewLeft(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                % Channel squares on the left
                for i = 1:numel(GrayProbeFilling)
                    % Call Lineclicked function if that happens
                    GrayProbeFilling(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end

                %% Add Max Value to yaxis labels so that 0 is up when yticks are flipped
            % % Get the current YLim and YTick values
            % yLimits = app.UIAxes.YLim;
            % currentYTicks = app.UIAxes.YTick;
            % currentYTickLabels = app.UIAxes.YTickLabel;
            % 
            % % Check if the maximum YLim value is already included in YTicks
            % if ~ismember(yLimits(2), currentYTicks)
            %     % If not, add the max Y value to the YTicks
            %     updatedYTicks = [currentYTicks, yLimits(2)];  % Append max Y value
            %     updatedYTickLabels = [currentYTickLabels; string(yLimits(2))];  % Append label for max Y value
            % 
            %     % Set the updated ticks and labels
            %     app.UIAxes.YTick = updatedYTicks;
            %     app.UIAxes.YTickLabel = updatedYTickLabels;
            % end
            % 
            % %% Flip yaxis
            % 
            % % Get current y-axis ticks and labels from the UIAxes object
            % currentYTicks = app.UIAxes.YTick;
            % currentYTickLabels = app.UIAxes.YTickLabel;
            % 
            % % Reverse the order of the labels
            % reversedYTickLabels = flip(currentYTickLabels);
            % 
            % % Set the new reversed labels to the y-axis
            % app.UIAxes.YTickLabel = reversedYTickLabels;

            %% Create Legend
            % Create a dummy red line plot for the legend
            dummyLine = plot(app.UIAxes2, NaN, NaN, 'Color', 'red', 'LineWidth', 2);
            
            % Create a dummy yellow rectangle using a patch for the legend
            dummyRect = patch(app.UIAxes2, NaN, NaN, 'yellow', 'EdgeColor', 'none');
            
            % Create the legend and position it at the center of the plot
            legend(app.UIAxes2, [dummyLine, dummyRect], {'All Active Channel', 'Currently Active Channel'}, 'AutoUpdate', 'off');
            
            % Position the legend in the middle of the figure (over the entire figure size)
            legendPosition = [0.6, 0.9, 0.15, 0.04]; % x, y, width, height (normalized)
            set(app.UIAxes2.Legend, 'Position', legendPosition);
                
            end
        end

        % Value changed function: VerticalOffsetumEditField
        function VerticalOffsetumEditFieldValueChanged(app, event)
            if ~isempty(app.NrChannelEditField.Value) && ~isempty(app.ChannelSpacingumEditField.Value)

                if str2double(app.ChannelRowsDropDown.Value) == 1
                    if str2double(app.NrChannelEditField.Value) >=32
                        app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-31;
                    else
                        app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-(str2double(app.NrChannelEditField.Value)-1);
                    end 

                    if isempty(app.ActiveChannelField.Value{1})
                        ActiveChannel = 1:str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value);
                    else
                        ActiveChannel = str2double(strsplit(app.ActiveChannelField.Value{1},','));
                    end

                    if isfield(app.ProbeTrajectoryInfo,'AreaNamesLong')
                        BrainAreaInfo = app.ProbeTrajectoryInfo;
                    else
                        BrainAreaInfo = [];
                    end

                    Utility_Plot_Interactive_Probe_View(app.UIAxes,str2double(app.ChannelSpacingumEditField.Value),str2double(app.NrChannelEditField.Value),str2double(app.ChannelRowsDropDown.Value),str2double(app.HorizontalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField.Value),app.ChannelOrderField.Value,ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,ActiveChannel,app.ShowChannelSpacingCheckBox.Value,1,1,[],app.CheckBox.Value,[])
                else
                    if str2double(app.NrChannelEditField.Value)*2 >=64
                        app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-31;
                    else
                        app.FirstZoomChannel = (str2double(app.NrChannelEditField.Value)-1)-(str2double(app.NrChannelEditField.Value));
                    end 

                    if isempty(app.ActiveChannelField.Value{1})
                        ActiveChannel = 1:str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value);
                    else
                        ActiveChannel = str2double(strsplit(app.ActiveChannelField.Value{1},','));
                    end

                    if isfield(app.ProbeTrajectoryInfo,'AreaNamesLong')
                        BrainAreaInfo = app.ProbeTrajectoryInfo;
                    else
                        BrainAreaInfo = [];
                    end

                    %app.FirstZoomChannel = str2double(app.NrChannelEditField.Value);
                    Utility_Plot_Interactive_Probe_View(app.UIAxes,str2double(app.ChannelSpacingumEditField.Value),str2double(app.NrChannelEditField.Value),str2double(app.ChannelRowsDropDown.Value),str2double(app.HorizontalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField.Value),app.ChannelOrderField.Value,ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,ActiveChannel,app.ShowChannelSpacingCheckBox.Value,1,1,[],app.CheckBox.Value,[])
                end


                %% Initiate Callback
                if isempty(app.UIAxes.ButtonDownFcn)
                    app.UIAxes.ButtonDownFcn = @(src1, event1) ProbeViewClickCallback(app, event1);
                end    
            
                % Add ButtonDownFcn to each line object in UIAxis
                lines = findobj(app.UIAxes, 'Type', 'line');
                
                % Add ButtonDownFcn to each line object in UIAxis
                ChannelViewRight = findobj(app.UIAxes,'Tag','ChannelViewRight');
            
                % Add ButtonDownFcn to each line object in UIAxis
                ChannelViewLeft = findobj(app.UIAxes,'Tag','ChannelViewLeft');
            
                % Add ButtonDownFcn to each line object in UIAxis
                GrayProbeFilling = findobj(app.UIAxes,'Tag','GrayProbeFilling');
                    
                %% Set the ButtonDownFcn for UIAxes to register clicks on a plotted line directly
                % lines plotted
                for i = 1:numel(lines)
                    % Call Lineclicked function if that happens
                    lines(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
            
                % Channel squares on the right
                for i = 1:numel(ChannelViewRight)
                    % Call Lineclicked function if that happens
                    ChannelViewRight(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                % Channel squares on the left
                for i = 1:numel(ChannelViewLeft)
                    % Call Lineclicked function if that happens
                    ChannelViewLeft(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                % Channel squares on the left
                for i = 1:numel(GrayProbeFilling)
                    % Call Lineclicked function if that happens
                    GrayProbeFilling(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end

                %% Add Max Value to yaxis labels so that 0 is up when yticks are flipped
            % % Get the current YLim and YTick values
            % yLimits = app.UIAxes.YLim;
            % currentYTicks = app.UIAxes.YTick;
            % currentYTickLabels = app.UIAxes.YTickLabel;
            % 
            % % Check if the maximum YLim value is already included in YTicks
            % if ~ismember(yLimits(2), currentYTicks)
            %     % If not, add the max Y value to the YTicks
            %     updatedYTicks = [currentYTicks, yLimits(2)];  % Append max Y value
            %     updatedYTickLabels = [currentYTickLabels; string(yLimits(2))];  % Append label for max Y value
            % 
            %     % Set the updated ticks and labels
            %     app.UIAxes.YTick = updatedYTicks;
            %     app.UIAxes.YTickLabel = updatedYTickLabels;
            % end
            
            % %% Flip yaxis
            % 
            % % Get current y-axis ticks and labels from the UIAxes object
            % currentYTicks = app.UIAxes.YTick;
            % currentYTickLabels = app.UIAxes.YTickLabel;
            % 
            % % Reverse the order of the labels
            % reversedYTickLabels = flip(currentYTickLabels);
            % 
            % % Set the new reversed labels to the y-axis
            % app.UIAxes.YTickLabel = reversedYTickLabels;

            %% Create Legend
            % Create a dummy red line plot for the legend
            dummyLine = plot(app.UIAxes2, NaN, NaN, 'Color', 'red', 'LineWidth', 2);
            
            % Create a dummy yellow rectangle using a patch for the legend
            dummyRect = patch(app.UIAxes2, NaN, NaN, 'yellow', 'EdgeColor', 'none');
            
            % Create the legend and position it at the center of the plot
            legend(app.UIAxes2, [dummyLine, dummyRect], {'All Active Channel', 'Currently Active Channel'}, 'AutoUpdate', 'off');
            
            % Position the legend in the middle of the figure (over the entire figure size)
            legendPosition = [0.6, 0.9, 0.15, 0.04]; % x, y, width, height (normalized)
            set(app.UIAxes2.Legend, 'Position', legendPosition);
                
            end
        end

        % Value changing function: ChannelSpacingumEditField
        function ChannelSpacingumEditFieldValueChanging(app, event)
            
        end

        % Menu selected function: SaveChannelOrderMenu
        function SaveChannelOrderMenuSelected(app, event)
            if ~isempty(app.ChannelOrderField.Value)

                cd(strcat(app.ExtractRecordingWindow.Mainapp.executableFolder,'\Probe Layouts\Channel Orders\'));

                % Split the string by commas
                split_array = strsplit(app.ChannelOrderField.Value{1}, ',');
                
                % Convert each element to double
                Channelorder = str2double(split_array);

                % Prompt user for file save location and name
                [file, path] = uiputfile('*.mat', 'Save as');
                
                if isequal(file,0) || isequal(path,0)
                    disp('User canceled the operation.');
                    return;
                end
        
                % Construct the full file path
                filepath = fullfile(path, file);
                % Save the variables to the .mat file
                save(filepath, 'Channelorder');
                stringtoshow = ['Channel Order saved to: ' filepath];
                msgbox(stringtoshow);
            else
                stringtoshow = 'No channel order specified';
                msgbox(stringtoshow);
            end
        end

        % Menu selected function: SaveProbeInfoforGUIMenu
        function SaveProbeInfoforGUIMenuSelected(app, event)
            if isempty(app.ChannelSpacingumEditField.Value)
                msgbox("Please first define a channelspacing! Returning.")
            end
            if isempty(app.NrChannelEditField.Value)
                msgbox("Please first define the number of channel! Returning.")
            end

            cd(strcat(app.ExtractRecordingWindow.Mainapp.executableFolder,'\Probe Layouts\Saved Probe Layouts\'));

            % Prompt user for file save location and name
            [file, path] = uiputfile('*.mat', 'Save as');
            
            if isequal(file,0) || isequal(path,0)
                disp('User canceled the operation.');
                return;
            end

            Savefilepath = fullfile(path,file);

            DatatoSave.ChannelOrder = app.ChannelOrderField.Value;
            

            if isempty(app.ActiveChannelField.Value{1})
                DatatoSave.ActiveChannel = 1:str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value);
            else
                if length(str2double(strsplit(app.ActiveChannelField.Value{1},','))) > str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value)
                    msgbox("Error: Number of active channel exceeds number of channel specified!")
                    return;
                else
                    DatatoSave.ActiveChannel = sort(str2double(strsplit(app.ActiveChannelField.Value{1},',')));
                end
            end

            DatatoSave.ChannelSpacing = app.ChannelSpacingumEditField.Value;

            DatatoSave.NumberChannelRows = app.ChannelRowsDropDown.Value;
            
            DatatoSave.NrChannel = app.NrChannelEditField.Value;
            DatatoSave.HorizontalOffsetum = app.HorizontalOffsetumEditField.Value;
            DatatoSave.VerticalOffsetum = app.VerticalOffsetumEditField.Value;

            DatatoSave.OffSetRows = app.CheckBox.Value;

            if ~isempty(app.VerticalOffsetumEditField_2.Value)
                DatatoSave.OffSetRowsDistance = app.VerticalOffsetumEditField_2.Value;
            else
                DatatoSave.OffSetRowsDistance = '0';
            end

            if ~isempty(app.ProbeTrajectoryInfo)
                if isfield(app.ProbeTrajectoryInfo,'AreaNamesLong')       
                    DatatoSave.AreaNamesLong = app.ProbeTrajectoryInfo.AreaNamesLong;
                    DatatoSave.AreaNamesShort = app.ProbeTrajectoryInfo.AreaNamesShort;
                    DatatoSave.AreaTipDistance = app.ProbeTrajectoryInfo.AreaTipDistance;
                end
            end

            save(Savefilepath,"DatatoSave");
            
            msgbox(strcat("Probe layout succesfully saved to: ",Savefilepath));
            disp(strcat("Probe layout succesfully saved to: ",Savefilepath));
        end

        % Menu selected function: SaveActiveChannelMenu
        function SaveActiveChannelMenuSelected(app, event)
            if ~isempty(app.ActiveChannelField.Value{1})

                cd(strcat(app.ExtractRecordingWindow.Mainapp.executableFolder,'\Probe Layouts\Active Channel\'));

                % Split the string by commas
                split_array = strsplit(app.ActiveChannelField.Value{1}, ',');
                
                % Convert each element to double
                ActiveChannel = str2double(split_array);

                % Prompt user for file save location and name
                [file, path] = uiputfile('*.mat', 'Save as');
                
                if isequal(file,0) || isequal(path,0)
                    disp('User canceled the operation.');
                    return;
                end
        
                % Construct the full file path
                filepath = fullfile(path, file);
                % Save the variables to the .mat file
                save(filepath, 'ActiveChannel');
                stringtoshow = ['Active channel map saved to: ' filepath];
                msgbox(stringtoshow);
            else
                stringtoshow = 'No active channel specified';
                msgbox(stringtoshow);
            end
        end

        % Menu selected function: SaveProbeInfoforKilosortMenu
        function SaveProbeInfoforKilosortMenuSelected(app, event)
            if ~isempty(app.NrChannelEditField.Value) && ~isempty(app.ChannelSpacingumEditField.Value)

                cd(strcat(app.ExtractRecordingWindow.Load_Data_Window_Info.Mainapp.executableFolder,'\Probe Layouts\Kilosort Channelmaps\'));

                if str2double(app.ChannelRowsDropDown.Value) == 1
                    chanMap = 1:str2double(app.NrChannelEditField.Value);
                    chanMap0ind = 0:str2double(app.NrChannelEditField.Value)-1;

                    if isempty(app.ActiveChannelField.Value{1})
                        connected = true(size(chanMap,1),size(chanMap,2));
                    else
                        activeChannels = str2double(strsplit(app.ActiveChannelField.Value{1},','));
                        connected = false(size(chanMap,1),size(chanMap,2));
                        connected(activeChannels) = true;
                    end
                    kcoords = zeros(size(chanMap,1),size(chanMap,2))+1;
                    xcoords = zeros(size(chanMap,1),size(chanMap,2));
                    ycoords = 0:str2double(app.ChannelSpacingumEditField.Value):(str2double(app.NrChannelEditField.Value)-1)*str2double(app.ChannelSpacingumEditField.Value);
                
                    %ycoords = str2double(app.ChannelSpacingumEditField.Value):str2double(app.ChannelSpacingumEditField.Value):(str2double(app.NrChannelEditField.Value))*str2double(app.ChannelSpacingumEditField.Value);
                end

                % Prompt user for file save location and name
                [file, path] = uiputfile('*.mat', 'Save as');
                
                if isequal(file,0) || isequal(path,0)
                    disp('User canceled the operation.');
                    return;
                end
        
                % Construct the full file path
                filepath = fullfile(path, file);
                % Save the variables to the .mat file
                save(filepath, 'chanMap', 'chanMap0ind', 'connected', 'kcoords', 'xcoords', 'ycoords');
                stringtoshow = ['Kilosort channel map saved to: ' filepath];
                msgbox(stringtoshow);
            else
                stringtoshow = 'Not all necessary parameters specified!';
                msgbox(stringtoshow);
            end
        end

        % Button pushed function: LoadActiveChannelSelectionButton
        function LoadActiveChannelSelectionButtonPushed(app, event)
            cd(strcat(app.ExtractRecordingWindow.Mainapp.executableFolder,'\Probe Layouts\Active Channel\'));
            %% Load a .mat file containing a vector with numbers indicating the true position of a channel on the probe
            % See ReadMe file in the ChannelOrder folder of the GUI 
            % stringtoshow = "Please select a .mat file containing your channel order (numbers).";
            % f = msgbox(stringtoshow);
            % Use uiget to to select a .mat file
            [file, path] = uigetfile('*.mat', 'Select a .mat file');
            
            % Check if the user cancels the operation
            if isequal(file,0) || isequal(path,0)
                disp('Operation canceled.');
                return;
            end
            
            % Load the selected .mat file and save in the mainapp
            tempActiveChannel = load(fullfile(path, file));
            % Get the field names of the structure
            fieldNames = fieldnames(tempActiveChannel);
            fieldName = fieldNames{1};

            app.ProbeInfoandPath.ActiveChannel = tempActiveChannel.(fieldName);

            %% Display the channelorder in the Window 

            texttoshow = sprintf('%d, ', app.ProbeInfoandPath.ActiveChannel);
            % Remove the trailing comma and whitespace
            texttoshow(end-1:end) = [];

            app.ActiveChannelField.Value{1} = texttoshow;

            if str2double(app.ChannelRowsDropDown.Value) == 1
                if str2double(app.NrChannelEditField.Value) >=32
                    app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-31;
                else
                    app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-(str2double(app.NrChannelEditField.Value)-1);
                end 

                if isempty(app.ActiveChannelField.Value{1})
                    ActiveChannel = 1:str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value);
                else
                    ActiveChannel = str2double(strsplit(app.ActiveChannelField.Value{1},','));
                end

                if isfield(app.ProbeTrajectoryInfo,'AreaNamesLong')
                        BrainAreaInfo = app.ProbeTrajectoryInfo;
                    else
                        BrainAreaInfo = [];
                end

                Utility_Plot_Interactive_Probe_View(app.UIAxes,str2double(app.ChannelSpacingumEditField.Value),str2double(app.NrChannelEditField.Value),str2double(app.ChannelRowsDropDown.Value),str2double(app.HorizontalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField.Value),app.ChannelOrderField.Value,ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,ActiveChannel,app.ShowChannelSpacingCheckBox.Value,1,1,[],app.CheckBox.Value,[])
            else
                if str2double(app.NrChannelEditField.Value)*2 >=64
                    app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-31;
                else
                    app.FirstZoomChannel = (str2double(app.NrChannelEditField.Value)-1)-(str2double(app.NrChannelEditField.Value));
                end 

                if isempty(app.ActiveChannelField.Value{1})
                    ActiveChannel = 1:str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value);
                else
                    ActiveChannel = str2double(strsplit(app.ActiveChannelField.Value{1},','));
                end

                if isfield(app.ProbeTrajectoryInfo,'AreaNamesLong')
                    BrainAreaInfo = app.ProbeTrajectoryInfo;
                else
                    BrainAreaInfo = [];
                end

                %app.FirstZoomChannel = str2double(app.NrChannelEditField.Value);
                Utility_Plot_Interactive_Probe_View(app.UIAxes,str2double(app.ChannelSpacingumEditField.Value),str2double(app.NrChannelEditField.Value),str2double(app.ChannelRowsDropDown.Value),str2double(app.HorizontalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField.Value),app.ChannelOrderField.Value,ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,ActiveChannel,app.ShowChannelSpacingCheckBox.Value,1,1,[],app.CheckBox.Value,[])
            end

            if ~isempty(app.NrChannelEditField.Value) && ~isempty(app.ChannelSpacingumEditField.Value)
                %% Initiate Callback
                if isempty(app.UIAxes.ButtonDownFcn)
                    app.UIAxes.ButtonDownFcn = @(src1, event1) ProbeViewClickCallback(app, event1);
                end    
            
                % Add ButtonDownFcn to each line object in UIAxis
                lines = findobj(app.UIAxes, 'Type', 'line');
                
                % Add ButtonDownFcn to each line object in UIAxis
                ChannelViewRight = findobj(app.UIAxes,'Tag','ChannelViewRight');
            
                % Add ButtonDownFcn to each line object in UIAxis
                ChannelViewLeft = findobj(app.UIAxes,'Tag','ChannelViewLeft');
            
                % Add ButtonDownFcn to each line object in UIAxis
                GrayProbeFilling = findobj(app.UIAxes,'Tag','GrayProbeFilling');
                    
                %% Set the ButtonDownFcn for UIAxes to register clicks on a plotted line directly
                % lines plotted
                for i = 1:numel(lines)
                    % Call Lineclicked function if that happens
                    lines(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
            
                % Channel squares on the right
                for i = 1:numel(ChannelViewRight)
                    % Call Lineclicked function if that happens
                    ChannelViewRight(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                % Channel squares on the left
                for i = 1:numel(ChannelViewLeft)
                    % Call Lineclicked function if that happens
                    ChannelViewLeft(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                % Channel squares on the left
                for i = 1:numel(GrayProbeFilling)
                    % Call Lineclicked function if that happens
                    GrayProbeFilling(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                
            end
        end

        % Value changed function: ActiveChannelField
        function ActiveChannelFieldValueChanged(app, event)
            if ~isempty(app.NrChannelEditField.Value) && ~isempty(app.ChannelSpacingumEditField.Value)

                TempActiveChannel{1} = [];
                if length(app.ActiveChannelField.Value)>1
                    for ncells = 1:length(app.ActiveChannelField.Value)
                        if ~isempty(app.ActiveChannelField.Value{ncells})
                            if ncells ==1
                                TempActiveChannel{1} = app.ActiveChannelField.Value{ncells};
                            else
                                TempActiveChannel{1} = [TempActiveChannel{1},',',app.ActiveChannelField.Value{ncells}];
                            end
                        end
                    end
                    app.ActiveChannelField.Value = '';
                    app.ActiveChannelField.Value{1} = TempActiveChannel{1};
                end

                if str2double(app.ChannelRowsDropDown.Value) == 1
                    if str2double(app.NrChannelEditField.Value) >=32
                        app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-31;
                    else
                        app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-(str2double(app.NrChannelEditField.Value)-1);
                    end 

                    if isempty(app.ActiveChannelField.Value{1})
                        ActiveChannel = 1:str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value);
                    else
                        ActiveChannel = str2double(strsplit(app.ActiveChannelField.Value{1},','));
                    end

                    if isfield(app.ProbeTrajectoryInfo,'AreaNamesLong')
                        BrainAreaInfo = app.ProbeTrajectoryInfo;
                    else
                        BrainAreaInfo = [];
                    end

                    Utility_Plot_Interactive_Probe_View(app.UIAxes,str2double(app.ChannelSpacingumEditField.Value),str2double(app.NrChannelEditField.Value),str2double(app.ChannelRowsDropDown.Value),str2double(app.HorizontalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField.Value),app.ChannelOrderField.Value,ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,ActiveChannel,app.ShowChannelSpacingCheckBox.Value,1,1,[],app.CheckBox.Value,[])
                else
                    if str2double(app.NrChannelEditField.Value)*2 >=64
                        app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-31;
                    else
                        app.FirstZoomChannel = (str2double(app.NrChannelEditField.Value)-1)-(str2double(app.NrChannelEditField.Value));
                    end 

                    if isempty(app.ActiveChannelField.Value{1})
                        ActiveChannel = 1:str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value);
                    else
                        ActiveChannel = str2double(strsplit(app.ActiveChannelField.Value{1},','));
                    end

                    if isfield(app.ProbeTrajectoryInfo,'AreaNamesLong')
                        BrainAreaInfo = app.ProbeTrajectoryInfo;
                    else
                        BrainAreaInfo = [];
                    end

                    %app.FirstZoomChannel = str2double(app.NrChannelEditField.Value);
                    Utility_Plot_Interactive_Probe_View(app.UIAxes,str2double(app.ChannelSpacingumEditField.Value),str2double(app.NrChannelEditField.Value),str2double(app.ChannelRowsDropDown.Value),str2double(app.HorizontalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField.Value),app.ChannelOrderField.Value,ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,ActiveChannel,app.ShowChannelSpacingCheckBox.Value,1,1,[],app.CheckBox.Value,[])
                end


                %% Initiate Callback
                if isempty(app.UIAxes.ButtonDownFcn)
                    app.UIAxes.ButtonDownFcn = @(src1, event1) ProbeViewClickCallback(app, event1);
                end    
            
                % Add ButtonDownFcn to each line object in UIAxis
                lines = findobj(app.UIAxes, 'Type', 'line');
                
                % Add ButtonDownFcn to each line object in UIAxis
                ChannelViewRight = findobj(app.UIAxes,'Tag','ChannelViewRight');
            
                % Add ButtonDownFcn to each line object in UIAxis
                ChannelViewLeft = findobj(app.UIAxes,'Tag','ChannelViewLeft');
            
                % Add ButtonDownFcn to each line object in UIAxis
                GrayProbeFilling = findobj(app.UIAxes,'Tag','GrayProbeFilling');
                    
                %% Set the ButtonDownFcn for UIAxes to register clicks on a plotted line directly
                % lines plotted
                for i = 1:numel(lines)
                    % Call Lineclicked function if that happens
                    lines(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
            
                % Channel squares on the right
                for i = 1:numel(ChannelViewRight)
                    % Call Lineclicked function if that happens
                    ChannelViewRight(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                % Channel squares on the left
                for i = 1:numel(ChannelViewLeft)
                    % Call Lineclicked function if that happens
                    ChannelViewLeft(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                % Channel squares on the left
                for i = 1:numel(GrayProbeFilling)
                    % Call Lineclicked function if that happens
                    GrayProbeFilling(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                
                %% Add Max Value to yaxis labels so that 0 is up when yticks are flipped
            % % Get the current YLim and YTick values
            % yLimits = app.UIAxes.YLim;
            % currentYTicks = app.UIAxes.YTick;
            % currentYTickLabels = app.UIAxes.YTickLabel;
            % 
            % % Check if the maximum YLim value is already included in YTicks
            % if ~ismember(yLimits(2), currentYTicks)
            %     % If not, add the max Y value to the YTicks
            %     updatedYTicks = [currentYTicks, yLimits(2)];  % Append max Y value
            %     updatedYTickLabels = [currentYTickLabels; string(yLimits(2))];  % Append label for max Y value
            % 
            %     % Set the updated ticks and labels
            %     app.UIAxes.YTick = updatedYTicks;
            %     app.UIAxes.YTickLabel = updatedYTickLabels;
            % end
            % 
            % %% Flip yaxis
            % 
            % % Get current y-axis ticks and labels from the UIAxes object
            % currentYTicks = app.UIAxes.YTick;
            % currentYTickLabels = app.UIAxes.YTickLabel;
            % 
            % % Reverse the order of the labels
            % reversedYTickLabels = flip(currentYTickLabels);
            % 
            % % Set the new reversed labels to the y-axis
            % app.UIAxes.YTickLabel = reversedYTickLabels;

            %% Create Legend
            % Create a dummy red line plot for the legend
            dummyLine = plot(app.UIAxes2, NaN, NaN, 'Color', 'red', 'LineWidth', 2);
            
            % Create a dummy yellow rectangle using a patch for the legend
            dummyRect = patch(app.UIAxes2, NaN, NaN, 'yellow', 'EdgeColor', 'none');
            
            % Create the legend and position it at the center of the plot
            legend(app.UIAxes2, [dummyLine, dummyRect], {'All Active Channel', 'Currently Active Channel'}, 'AutoUpdate', 'off');
            
            % Position the legend in the middle of the figure (over the entire figure size)
            legendPosition = [0.6, 0.9, 0.15, 0.04]; % x, y, width, height (normalized)
            set(app.UIAxes2.Legend, 'Position', legendPosition);

            end
        end

        % Value changed function: ChannelOrderField
        function ChannelOrderFieldValueChanged(app, event)
          TempChannelOrder{1} = [];
        if length(app.ChannelOrderField.Value)>1
            for ncells = 1:length(app.ChannelOrderField.Value)
                if ~isempty(app.ChannelOrderField.Value{ncells})
                    if ncells ==1
                        TempChannelOrder{1} = app.ChannelOrderField.Value{ncells};
                    else
                        TempChannelOrder{1} = [TempChannelOrder{1},',',app.ChannelOrderField.Value{ncells}];
                    end
                end
            end
            app.ChannelOrderField.Value = '';
            app.ChannelOrderField.Value{1} = TempChannelOrder{1};
        end
            
        end

        % Menu selected function: Menu
        function MenuSelected(app, event)
            neuropixels_trajectory_explorer;
        end

        % Menu selected function: LoadSavedTrajectoryMenu
        function LoadSavedTrajectoryMenuSelected(app, event)
            cd(strcat(app.Mainapp.executableFolder,'\Probe Layouts\Trajectories\'));

            [fileName, filePath] = uigetfile('*.mat*', 'Select a File to Load');
            
            % Check if the user selected a file or canceled the dialog
            if isequal(fileName, 0)
                disp('File selection canceled.');
            else
                fullFileName = fullfile(filePath, fileName);

                % Load the file (assuming it's a .mat file)
                LoadedProbeTrajectoryInfo = load(fullFileName);
                Areas = LoadedProbeTrajectoryInfo.probe_areas;

                app.ProbeTrajectoryInfo.AreaNamesLong = Areas{1}.name;
                app.ProbeTrajectoryInfo.AreaNamesShort = Areas{1}.acronym;
                
                app.ProbeTrajectoryInfo.AreaTipDistance = Areas{1}.tip_distance;
                
                disp('Probe Trajectory Info successfully loaded.');
                
            end
            
            if ~isempty(app.NrChannelEditField.Value) && ~isempty(app.ChannelSpacingumEditField.Value)

                if str2double(app.ChannelRowsDropDown.Value) == 1
                    if str2double(app.NrChannelEditField.Value) >=32
                        app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-31;
                    else
                        app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-(str2double(app.NrChannelEditField.Value)-1);
                    end 

                    if isempty(app.ActiveChannelField.Value{1})
                        ActiveChannel = 1:str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value);
                    else
                        ActiveChannel = str2double(strsplit(app.ActiveChannelField.Value{1},','));
                    end

                    if isfield(app.ProbeTrajectoryInfo,'AreaNamesLong')
                        BrainAreaInfo = app.ProbeTrajectoryInfo;
                    else
                        BrainAreaInfo = [];
                    end

                    Utility_Plot_Interactive_Probe_View(app.UIAxes,str2double(app.ChannelSpacingumEditField.Value),str2double(app.NrChannelEditField.Value),str2double(app.ChannelRowsDropDown.Value),str2double(app.HorizontalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField.Value),app.ChannelOrderField.Value,ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,ActiveChannel,app.ShowChannelSpacingCheckBox.Value,1,1,[],app.CheckBox.Value,[])
                else
                    if str2double(app.NrChannelEditField.Value)*2 >=64
                        app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-31;
                    else
                        app.FirstZoomChannel = (str2double(app.NrChannelEditField.Value)-1)-(str2double(app.NrChannelEditField.Value));
                    end 

                    if isempty(app.ActiveChannelField.Value{1})
                        ActiveChannel = 1:str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value);
                    else
                        ActiveChannel = str2double(strsplit(app.ActiveChannelField.Value{1},','));
                    end

                    if isfield(app.ProbeTrajectoryInfo,'AreaNamesLong')
                        BrainAreaInfo = app.ProbeTrajectoryInfo;
                    else
                        BrainAreaInfo = [];
                    end

                    %app.FirstZoomChannel = str2double(app.NrChannelEditField.Value);
                    Utility_Plot_Interactive_Probe_View(app.UIAxes,str2double(app.ChannelSpacingumEditField.Value),str2double(app.NrChannelEditField.Value),str2double(app.ChannelRowsDropDown.Value),str2double(app.HorizontalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField.Value),app.ChannelOrderField.Value,ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,ActiveChannel,app.ShowChannelSpacingCheckBox.Value,1,1,[],app.CheckBox.Value,[])
                end


                %% Initiate Callback
                if isempty(app.UIAxes.ButtonDownFcn)
                    app.UIAxes.ButtonDownFcn = @(src1, event1) ProbeViewClickCallback(app, event1);
                end    
            
                % Add ButtonDownFcn to each line object in UIAxis
                lines = findobj(app.UIAxes, 'Type', 'line');
                
                % Add ButtonDownFcn to each line object in UIAxis
                ChannelViewRight = findobj(app.UIAxes,'Tag','ChannelViewRight');
            
                % Add ButtonDownFcn to each line object in UIAxis
                ChannelViewLeft = findobj(app.UIAxes,'Tag','ChannelViewLeft');
            
                % Add ButtonDownFcn to each line object in UIAxis
                GrayProbeFilling = findobj(app.UIAxes,'Tag','GrayProbeFilling');
                    
                %% Set the ButtonDownFcn for UIAxes to register clicks on a plotted line directly
                % lines plotted
                for i = 1:numel(lines)
                    % Call Lineclicked function if that happens
                    lines(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
            
                % Channel squares on the right
                for i = 1:numel(ChannelViewRight)
                    % Call Lineclicked function if that happens
                    ChannelViewRight(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                % Channel squares on the left
                for i = 1:numel(ChannelViewLeft)
                    % Call Lineclicked function if that happens
                    ChannelViewLeft(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                % Channel squares on the left
                for i = 1:numel(GrayProbeFilling)
                    % Call Lineclicked function if that happens
                    GrayProbeFilling(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                
            end
        end

        % Value changed function: ShowChannelSpacingCheckBox
        function ShowChannelSpacingCheckBoxValueChanged(app, event)
            if ~isempty(app.NrChannelEditField.Value) && ~isempty(app.ChannelSpacingumEditField.Value)

                if str2double(app.ChannelRowsDropDown.Value) == 1
                    if str2double(app.NrChannelEditField.Value) >=32
                        app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-31;
                    else
                        app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-(str2double(app.NrChannelEditField.Value)-1);
                    end 

                    if isempty(app.ActiveChannelField.Value{1})
                        ActiveChannel = 1:str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value);
                    else
                        ActiveChannel = str2double(strsplit(app.ActiveChannelField.Value{1},','));
                    end

                    if isfield(app.ProbeTrajectoryInfo,'AreaNamesLong')
                        BrainAreaInfo = app.ProbeTrajectoryInfo;
                    else
                        BrainAreaInfo = [];
                    end

                    Utility_Plot_Interactive_Probe_View(app.UIAxes,str2double(app.ChannelSpacingumEditField.Value),str2double(app.NrChannelEditField.Value),str2double(app.ChannelRowsDropDown.Value),str2double(app.HorizontalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField.Value),app.ChannelOrderField.Value,ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,ActiveChannel,app.ShowChannelSpacingCheckBox.Value,1,1,[],app.CheckBox.Value,[])
                else
                    if str2double(app.NrChannelEditField.Value)*2 >=64
                        app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-31;
                    else
                        app.FirstZoomChannel = (str2double(app.NrChannelEditField.Value)-1)-(str2double(app.NrChannelEditField.Value));
                    end 

                    if isempty(app.ActiveChannelField.Value{1})
                        ActiveChannel = 1:str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value);
                    else
                        ActiveChannel = str2double(strsplit(app.ActiveChannelField.Value{1},','));
                    end

                    if isfield(app.ProbeTrajectoryInfo,'AreaNamesLong')
                        BrainAreaInfo = app.ProbeTrajectoryInfo;
                    else
                        BrainAreaInfo = [];
                    end

                    Utility_Plot_Interactive_Probe_View(app.UIAxes,str2double(app.ChannelSpacingumEditField.Value),str2double(app.NrChannelEditField.Value),str2double(app.ChannelRowsDropDown.Value),str2double(app.HorizontalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField.Value),app.ChannelOrderField.Value,ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,ActiveChannel,app.ShowChannelSpacingCheckBox.Value,1,1,[],app.CheckBox.Value,[])
                end


                %% Initiate Callback
                if isempty(app.UIAxes.ButtonDownFcn)
                    app.UIAxes.ButtonDownFcn = @(src1, event1) ProbeViewClickCallback(app, event1);
                end    
            
                % Add ButtonDownFcn to each line object in UIAxis
                lines = findobj(app.UIAxes, 'Type', 'line');
                
                % Add ButtonDownFcn to each line object in UIAxis
                ChannelViewRight = findobj(app.UIAxes,'Tag','ChannelViewRight');
            
                % Add ButtonDownFcn to each line object in UIAxis
                ChannelViewLeft = findobj(app.UIAxes,'Tag','ChannelViewLeft');
            
                % Add ButtonDownFcn to each line object in UIAxis
                GrayProbeFilling = findobj(app.UIAxes,'Tag','GrayProbeFilling');
                    
                %% Set the ButtonDownFcn for UIAxes to register clicks on a plotted line directly
                % lines plotted
                for i = 1:numel(lines)
                    % Call Lineclicked function if that happens
                    lines(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
            
                % Channel squares on the right
                for i = 1:numel(ChannelViewRight)
                    % Call Lineclicked function if that happens
                    ChannelViewRight(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                % Channel squares on the left
                for i = 1:numel(ChannelViewLeft)
                    % Call Lineclicked function if that happens
                    ChannelViewLeft(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                % Channel squares on the left
                for i = 1:numel(GrayProbeFilling)
                    % Call Lineclicked function if that happens
                    GrayProbeFilling(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                
                %% Add Max Value to yaxis labels so that 0 is up when yticks are flipped
            % % Get the current YLim and YTick values
            % yLimits = app.UIAxes.YLim;
            % currentYTicks = app.UIAxes.YTick;
            % currentYTickLabels = app.UIAxes.YTickLabel;
            % 
            % % Check if the maximum YLim value is already included in YTicks
            % if ~ismember(yLimits(2), currentYTicks)
            %     % If not, add the max Y value to the YTicks
            %     updatedYTicks = [currentYTicks, yLimits(2)];  % Append max Y value
            %     updatedYTickLabels = [currentYTickLabels; string(yLimits(2))];  % Append label for max Y value
            % 
            %     % Set the updated ticks and labels
            %     app.UIAxes.YTick = updatedYTicks;
            %     app.UIAxes.YTickLabel = updatedYTickLabels;
            % end
            
            % %% Flip yaxis
            % 
            % % Get current y-axis ticks and labels from the UIAxes object
            % currentYTicks = app.UIAxes.YTick;
            % currentYTickLabels = app.UIAxes.YTickLabel;
            % 
            % % Reverse the order of the labels
            % reversedYTickLabels = flip(currentYTickLabels);
            % 
            % % Set the new reversed labels to the y-axis
            % app.UIAxes.YTickLabel = reversedYTickLabels;

            %% Create Legend
            % Create a dummy red line plot for the legend
            dummyLine = plot(app.UIAxes2, NaN, NaN, 'Color', 'red', 'LineWidth', 2);
            
            % Create a dummy yellow rectangle using a patch for the legend
            dummyRect = patch(app.UIAxes2, NaN, NaN, 'yellow', 'EdgeColor', 'none');
            
            % Create the legend and position it at the center of the plot
            legend(app.UIAxes2, [dummyLine, dummyRect], {'All Active Channel', 'Currently Active Channel'}, 'AutoUpdate', 'off');
            
            % Position the legend in the middle of the figure (over the entire figure size)
            legendPosition = [0.6, 0.9, 0.15, 0.04]; % x, y, width, height (normalized)
            set(app.UIAxes2.Legend, 'Position', legendPosition);

            end
            
        end

        % Value changing function: NrChannelEditField
        function NrChannelEditFieldValueChanging(app, event)
           
        end

        % Close request function: ProbeLayoutWindowUIFigure
        function ProbeLayoutWindowUIFigureCloseRequest(app, event)
            app.ExtractRecordingWindow.ProbeLayoutWindow = [];
            delete(app)
        end

        % Value changed function: VerticalOffsetumEditField_2
        function VerticalOffsetumEditField_2ValueChanged(app, event)
            value = app.VerticalOffsetumEditField_2.Value;
            
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
           
            if ~isempty(app.NrChannelEditField.Value) && ~isempty(app.ChannelSpacingumEditField.Value)

                if str2double(app.ChannelRowsDropDown.Value) == 1
                    if str2double(app.NrChannelEditField.Value) >=32
                        app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-31;
                    else
                        app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-(str2double(app.NrChannelEditField.Value)-1);
                    end 

                    if isempty(app.ActiveChannelField.Value{1})
                        ActiveChannel = 1:str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value);
                    else
                        ActiveChannel = str2double(strsplit(app.ActiveChannelField.Value{1},','));
                    end
                    
                    if isfield(app.ProbeTrajectoryInfo,'AreaNamesLong')
                            BrainAreaInfo = app.ProbeTrajectoryInfo;
                        else
                            BrainAreaInfo = [];
                    end

                    Utility_Plot_Interactive_Probe_View(app.UIAxes,str2double(app.ChannelSpacingumEditField.Value),str2double(app.NrChannelEditField.Value),str2double(app.ChannelRowsDropDown.Value),str2double(app.HorizontalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField.Value),app.ChannelOrderField.Value,ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,ActiveChannel,app.ShowChannelSpacingCheckBox.Value,1,1,[],app.CheckBox.Value,[])
                else
                    if str2double(app.NrChannelEditField.Value)*2 >=64
                        app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-31;
                    else
                        app.FirstZoomChannel = (str2double(app.NrChannelEditField.Value)-1)-(str2double(app.NrChannelEditField.Value));
                    end 

                    if isempty(app.ActiveChannelField.Value{1})
                        ActiveChannel = 1:str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value);
                    else
                        ActiveChannel = str2double(strsplit(app.ActiveChannelField.Value{1},','));
                    end

                    if isfield(app.ProbeTrajectoryInfo,'AreaNamesLong')
                        BrainAreaInfo = app.ProbeTrajectoryInfo;
                    else
                        BrainAreaInfo = [];
                    end

                    %app.FirstZoomChannel = str2double(app.NrChannelEditField.Value);
                    Utility_Plot_Interactive_Probe_View(app.UIAxes,str2double(app.ChannelSpacingumEditField.Value),str2double(app.NrChannelEditField.Value),str2double(app.ChannelRowsDropDown.Value),str2double(app.HorizontalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField.Value),app.ChannelOrderField.Value,ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,ActiveChannel,app.ShowChannelSpacingCheckBox.Value,1,1,[],app.CheckBox.Value,[])
                end


                %% Initiate Callback
                if isempty(app.UIAxes.ButtonDownFcn)
                    app.UIAxes.ButtonDownFcn = @(src1, event1) ProbeViewClickCallback(app, event1);
                end    
            
                % Add ButtonDownFcn to each line object in UIAxis
                lines = findobj(app.UIAxes, 'Type', 'line');
                
                % Add ButtonDownFcn to each line object in UIAxis
                ChannelViewRight = findobj(app.UIAxes,'Tag','ChannelViewRight');
            
                % Add ButtonDownFcn to each line object in UIAxis
                ChannelViewLeft = findobj(app.UIAxes,'Tag','ChannelViewLeft');
            
                % Add ButtonDownFcn to each line object in UIAxis
                GrayProbeFilling = findobj(app.UIAxes,'Tag','GrayProbeFilling');
                    
                %% Set the ButtonDownFcn for UIAxes to register clicks on a plotted line directly
                % lines plotted
                for i = 1:numel(lines)
                    % Call Lineclicked function if that happens
                    lines(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
            
                % Channel squares on the right
                for i = 1:numel(ChannelViewRight)
                    % Call Lineclicked function if that happens
                    ChannelViewRight(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                % Channel squares on the left
                for i = 1:numel(ChannelViewLeft)
                    % Call Lineclicked function if that happens
                    ChannelViewLeft(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                % Channel squares on the left
                for i = 1:numel(GrayProbeFilling)
                    % Call Lineclicked function if that happens
                    GrayProbeFilling(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end

                %% Create Legend
                % Create a dummy red line plot for the legend
                dummyLine = plot(app.UIAxes2, NaN, NaN, 'Color', 'red', 'LineWidth', 2);
                
                % Create a dummy yellow rectangle using a patch for the legend
                dummyRect = patch(app.UIAxes2, NaN, NaN, 'yellow', 'EdgeColor', 'none');
                
                % Create the legend and position it at the center of the plot
                legend(app.UIAxes2, [dummyLine, dummyRect], {'All Active Channel', 'Currently Active Channel'}, 'AutoUpdate', 'off');
                
                % Position the legend in the middle of the figure (over the entire figure size)
                legendPosition = [0.6, 0.9, 0.15, 0.04]; % x, y, width, height (normalized)
                set(app.UIAxes2.Legend, 'Position', legendPosition);
                
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ProbeLayoutWindowUIFigure = uifigure('Visible', 'off');
            app.ProbeLayoutWindowUIFigure.Name = 'Probe Layout Window';
            app.ProbeLayoutWindowUIFigure.Icon = 'Logo.png';
            app.ProbeLayoutWindowUIFigure.CloseRequestFcn = createCallbackFcn(app, @ProbeLayoutWindowUIFigureCloseRequest, true);
            app.SaveProbeLayoutMenu = uimenu(app.ProbeLayoutWindowUIFigure);
            app.SaveProbeLayoutMenu.Text = '   Save Probe Layout   ';
            app.SaveProbeInfoforKilosortMenu = uimenu(app.SaveProbeLayoutMenu);
            app.SaveProbeInfoforKilosortMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveProbeInfoforKilosortMenuSelected, true);
            app.SaveProbeInfoforKilosortMenu.Text = 'Save Probe Info for Kilosort';
            app.SaveProbeInfoforGUIMenu = uimenu(app.SaveProbeLayoutMenu);
            app.SaveProbeInfoforGUIMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveProbeInfoforGUIMenuSelected, true);
            app.SaveProbeInfoforGUIMenu.Text = 'Save Probe Info for GUI';
            app.SaveChannelOrderMenu = uimenu(app.SaveProbeLayoutMenu);
            app.SaveChannelOrderMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveChannelOrderMenuSelected, true);
            app.SaveChannelOrderMenu.Text = 'Save Channel Order';
            app.SaveActiveChannelMenu = uimenu(app.SaveProbeLayoutMenu);
            app.SaveActiveChannelMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveActiveChannelMenuSelected, true);
            app.SaveActiveChannelMenu.Text = 'Save Active Channel';
            app.TrajectoryExplorerMenu = uimenu(app.ProbeLayoutWindowUIFigure);
            app.TrajectoryExplorerMenu.Text = '   Trajectory Explorer   ';
            app.Menu = uimenu(app.TrajectoryExplorerMenu);
            app.Menu.MenuSelectedFcn = createCallbackFcn(app, @MenuSelected, true);
            app.Menu.Text = 'Start Trajectory Explorer (requires to download Allen mouse brain atlas)';
            app.LoadSavedTrajectoryMenu = uimenu(app.TrajectoryExplorerMenu);
            app.LoadSavedTrajectoryMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadSavedTrajectoryMenuSelected, true);
            app.LoadSavedTrajectoryMenu.Text = 'Load Saved Trajectory';
            app.UIAxes = uiaxes(app.ProbeLayoutWindowUIFigure);
            app.UIAxes.XTickLabel = '';
            app.UIAxes2 = uiaxes(app.ProbeLayoutWindowUIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.ZTick = [];
            app.SetChannelInformationPanel = uipanel(app.ProbeLayoutWindowUIFigure);
            app.SetChannelInformationPanel.Title = 'Set Channel Information';
            app.ChannelOrderFormat1234LeaveemptyfornocostumorderLabel = uilabel(app.SetChannelInformationPanel);
            app.ChannelOrderFormat1234LeaveemptyfornocostumorderLabel.Text = {'Format: 1,2,3,4'; 'Leave empty for no costum order'};
            app.ChannelOrderField = uitextarea(app.SetChannelInformationPanel);
            app.ChannelOrderField.ValueChangedFcn = createCallbackFcn(app, @ChannelOrderFieldValueChanged, true);
            app.ChannelOrderFormat1234LeaveemptyfornocostumorderLabel_2 = uilabel(app.SetChannelInformationPanel);
            app.ChannelOrderFormat1234LeaveemptyfornocostumorderLabel_2.Text = {'Format: 1,2,3,4'; 'Leave empty for all channel '; 'being active'};
            app.ActiveChannelField = uitextarea(app.SetChannelInformationPanel);
            app.ActiveChannelField.ValueChangedFcn = createCallbackFcn(app, @ActiveChannelFieldValueChanged, true);
            app.LoadActiveChannelSelectionButton = uibutton(app.SetChannelInformationPanel, 'push');
            app.LoadActiveChannelSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @LoadActiveChannelSelectionButtonPushed, true);
            app.LoadActiveChannelSelectionButton.Text = {'Load Active'; 'Channel Selection'};
            app.LoadChannelOrderButton = uibutton(app.SetChannelInformationPanel, 'push');
            app.LoadChannelOrderButton.ButtonPushedFcn = createCallbackFcn(app, @LoadChannelOrderButtonPushed, true);
            app.LoadChannelOrderButton.Text = {'Load Channel '; 'Order'};
            app.ChannelOrderjustforactiveChannelLabel = uilabel(app.SetChannelInformationPanel);
            app.ChannelOrderjustforactiveChannelLabel.Text = {'Channel Order'; '(just for active Channel!)'};
            app.ActiveChannelLabel_2 = uilabel(app.SetChannelInformationPanel);
            app.ActiveChannelLabel_2.Text = 'Active Channel';
            app.InteractiveProbeViewLabel = uilabel(app.ProbeLayoutWindowUIFigure);
            app.InteractiveProbeViewLabel.Text = 'Interactive Probe View';
            app.CloseButton = uibutton(app.ProbeLayoutWindowUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed2, true);
            app.CloseButton.Text = 'Close';
            app.SetProbeGeometryPanel = uipanel(app.ProbeLayoutWindowUIFigure);
            app.SetProbeGeometryPanel.Title = 'Set Probe Geometry';
            app.ChannelSpacingumLabel = uilabel(app.SetProbeGeometryPanel);
            app.ChannelSpacingumLabel.Text = {'Channel Spacing '; '[µm]'};
            app.ChannelSpacingumEditField = uieditfield(app.SetProbeGeometryPanel, 'text');
            app.ChannelSpacingumEditField.ValueChangedFcn = createCallbackFcn(app, @ChannelSpacingumEditFieldValueChanged, true);
            app.ChannelSpacingumEditField.ValueChangingFcn = createCallbackFcn(app, @ChannelSpacingumEditFieldValueChanging, true);
            app.ChannelRowsDropDownLabel = uilabel(app.SetProbeGeometryPanel);
            app.ChannelRowsDropDownLabel.Text = 'Channel Rows';
            app.ChannelRowsDropDown = uidropdown(app.SetProbeGeometryPanel);
            app.ChannelRowsDropDown.Items = {'1', '2'};
            app.ChannelRowsDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelRowsDropDownValueChanged, true);
            app.ChannelRowsDropDown.Value = '1';
            app.VerticalOffsetBetweenChannelRowsumLabel = uilabel(app.SetProbeGeometryPanel);
            app.VerticalOffsetBetweenChannelRowsumLabel.Text = {'Vertical Offset'; 'Between Channel Rows [µm]'};
            app.VerticalOffsetumEditField = uieditfield(app.SetProbeGeometryPanel, 'text');
            app.VerticalOffsetumEditField.ValueChangedFcn = createCallbackFcn(app, @VerticalOffsetumEditFieldValueChanged, true);
            app.VerticalOffsetumEditField.Value = '0';
            app.NrChannelperRowEditFieldLabel = uilabel(app.SetProbeGeometryPanel);
            app.NrChannelperRowEditFieldLabel.Text = {'Nr Channel'; 'per Row'};
            app.NrChannelEditField = uieditfield(app.SetProbeGeometryPanel, 'text');
            app.NrChannelEditField.ValueChangedFcn = createCallbackFcn(app, @NrChannelEditFieldValueChanged, true);
            app.NrChannelEditField.ValueChangingFcn = createCallbackFcn(app, @NrChannelEditFieldValueChanging, true);
            app.HorizontalOffsetBetweenChannelRowsmLabel = uilabel(app.SetProbeGeometryPanel);
            app.HorizontalOffsetBetweenChannelRowsmLabel.Text = {'Horizontal Offset'; 'Between Channel Rows [µm]'};
            app.HorizontalOffsetumEditField = uieditfield(app.SetProbeGeometryPanel, 'text');
            app.HorizontalOffsetumEditField.ValueChangedFcn = createCallbackFcn(app, @HorizontalOffsetumEditFieldValueChanged, true);
            app.HorizontalOffsetumEditField.Value = '0';
            app.VerticalOffsetBetweenChannelRowsumLabel_2 = uilabel(app.SetProbeGeometryPanel);
            app.VerticalOffsetBetweenChannelRowsumLabel_2.Text = {'Vertical Offset Between'; 'Every Second Row [µm]'};
            app.VerticalOffsetumEditField_2 = uieditfield(app.SetProbeGeometryPanel, 'text');
            app.VerticalOffsetumEditField_2.ValueChangedFcn = createCallbackFcn(app, @VerticalOffsetumEditField_2ValueChanged, true);
            app.VerticalOffsetumEditField_2.Value = '0';
            app.CheckBox = uicheckbox(app.SetProbeGeometryPanel);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.ProbeLayoutWindowLabel = uilabel(app.ProbeLayoutWindowUIFigure);
            app.ProbeLayoutWindowLabel.Text = 'Probe Layout Window';
            app.Image = uiimage(app.ProbeLayoutWindowUIFigure);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodLabel = uilabel(app.ProbeLayoutWindowUIFigure);
            app.NeuromodLabel.Text = 'Neuromod';
            app.SetProbeInformationandContinueButton = uibutton(app.ProbeLayoutWindowUIFigure, 'push');
            app.SetProbeInformationandContinueButton.ButtonPushedFcn = createCallbackFcn(app, @SetProbeInformationandContinueButtonPushed, true);
            app.SetProbeInformationandContinueButton.Text = {'Set Probe Information '; 'and Continue'};
            app.ShowChannelSpacingCheckBox = uicheckbox(app.ProbeLayoutWindowUIFigure);
            app.ShowChannelSpacingCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowChannelSpacingCheckBoxValueChanged, true);
            app.ShowChannelSpacingCheckBox.Text = '    Show Channel Spacing';
            % Show the figure after all components are created
            app.ProbeLayoutWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Probe_Layout_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ProbeLayoutWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ProbeLayoutWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ProbeLayoutWindowUIFigure)
        end
    end
end