classdef Spike2_Select_Event_Channel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Spike2SelectEventChannelUIFigure  matlab.ui.Figure
        GridLayout     matlab.ui.container.GridLayout
        LeftPanel      matlab.ui.container.Panel
        RightPanel     matlab.ui.container.Panel
        TextArea_2     matlab.ui.control.TextArea
        TextArea       matlab.ui.control.TextArea
        Image          matlab.ui.control.Image
        NeuromodLabel  matlab.ui.control.Label
        Spike2EventchannelSelectionWindowLabel  matlab.ui.control.Label
        CloseButton    matlab.ui.control.Button
        Panel          matlab.ui.container.Panel
        EventChannelSelectionIntemptyfornonEditField  matlab.ui.control.EditField
        EventChannelSelectionIntemptyfornonEditFieldLabel  matlab.ui.control.Label
        ProceedButton  matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        SpikeEventChannel = []; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, SpikeEventChannel)
            movegui(app.Spike2SelectEventChannelUIFigure, 'center');

            app.SpikeEventChannel = SpikeEventChannel;
                       
        end

        % Button pushed function: ProceedButton
        function ProceedButtonPushed(app, event)
            % Step 1: Split the char array into individual strings using the comma as a delimiter
            if length(app.EventChannelSelectionIntemptyfornonEditField.Value)>1
                splitStrings = split(app.EventChannelSelectionIntemptyfornonEditField.Value, ',');
                
                % Step 2: Convert the split strings to numeric values
                app.SpikeEventChannel = str2double(splitStrings);
            else
                app.SpikeEventChannel = str2double(app.EventChannelSelectionIntemptyfornonEditField.Value);
            end

            
            % Resume execution to return the output
            uiresume(app.Spike2SelectEventChannelUIFigure);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.Spike2SelectEventChannelUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {272, 272};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Spike2SelectEventChannelUIFigure = uifigure('Visible', 'off');
            app.Spike2SelectEventChannelUIFigure.Name = 'Spike2 Select Event Channel';
            app.Spike2SelectEventChannelUIFigure.Icon = 'Logo.png';
            app.Spike2SelectEventChannelUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.Spike2SelectEventChannelUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.Panel = uipanel(app.RightPanel);
            app.ProceedButton = uibutton(app.Panel, 'push');
            app.ProceedButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedButtonPushed, true);
            app.ProceedButton.Text = 'Proceed';
            app.EventChannelSelectionIntemptyfornonEditFieldLabel = uilabel(app.Panel);
            app.EventChannelSelectionIntemptyfornonEditFieldLabel.Text = {'Event Channel'; 'Selection; Int'; '(empty for non)'};
            app.EventChannelSelectionIntemptyfornonEditField = uieditfield(app.Panel, 'text');
            app.CloseButton = uibutton(app.RightPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.Spike2EventchannelSelectionWindowLabel = uilabel(app.RightPanel);
            app.Spike2EventchannelSelectionWindowLabel.Text = 'Spike2 Eventchannel Selection Window';
            app.NeuromodLabel = uilabel(app.RightPanel);
            app.NeuromodLabel.Text = 'Neuromod';
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = 'Logo.png';
            app.TextArea = uitextarea(app.RightPanel);
            app.TextArea.Editable = 'off';
            app.TextArea.Enable = 'off';
            app.TextArea.Visible = 'off';
            app.TextArea_2 = uitextarea(app.RightPanel);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Enable = 'off';
            app.TextArea_2.Visible = 'off';
            % Show the figure after all components are created
            app.Spike2SelectEventChannelUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Spike2_Select_Event_Channel(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.Spike2SelectEventChannelUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.Spike2SelectEventChannelUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Spike2SelectEventChannelUIFigure)
        end
    end
end