classdef Save_Data_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SaveDataWindowUIFigure          matlab.ui.Figure
        InformationLabel                matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        CloseButton                     matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        NeuromodToolboxLabel            matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        SaveDataWindowLabel             matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        SelectAllButton                 matlab.ui.control.StateButton
        PreprocessedEventRelatedDataButton  matlab.ui.control.StateButton
        SaveTypeDropDown                matlab.ui.control.DropDown
        SaveasDropDownLabel             matlab.ui.control.Label
        MultipleSelectionpossibleLabel  matlab.ui.control.Label
        EventRelatedDataButton          matlab.ui.control.StateButton
        WhattosaveLabel                 matlab.ui.control.Label
        SpikeIndiciesButton             matlab.ui.control.StateButton
        EventTimesButton                matlab.ui.control.StateButton
        PreprocessedDataButton          matlab.ui.control.StateButton
        RawDataButton                   matlab.ui.control.StateButton
    end

    
    properties (Access = public)
        Mainapp = []; % To save Main Window object
        Whattosave = []; % To capture, which datacomponents have to be saved
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            movegui(app.SaveDataWindowUIFigure, 'center');
            app.TextArea.Value = "Please select a file type and data components you want to save. File type .dat is recommended. If saved as .dat file, an Info.mat file containing the recording information is created too (same name as the .dat file with an 'Info' at the end).";
            app.Mainapp = Mainapp;

            % Initiate vectzor holding Info what should be saved. Each
            % indicie of Whattosave corresponds to a component of the
            % dataset. A 0 indicates, this component should not be saved.
            % Vector gets changed when user changes the selection. 
            % Components in the correct order are:
            % [Raw,Preprocessed,Events,Spikes,EventrelatedData] --> [1,1,1,0,0] saves raw data, preprocessed data and event time points

            app.Whattosave = zeros(1,6);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            %% Check if data is saved or just events, spikes or info. If no
            % data display warning
            
            %% Start Saving Data
            app.TextArea.Value = "Data is saved, please wait until this window closes";
            pause(0.2);

            [filepath,Error] = Manage_Dataset_Module_SaveData(app.Mainapp.Data,app.SaveTypeDropDown.Value,app.Whattosave,app.Mainapp.executableFolder,"No");
            
            %% Check if Saving was succesfull.
            if ~isempty(filepath) && Error == 0
                app.TextArea.Value = strcat("Data was succesfully saved to: ",filepath);
                pause(0.2);
            else
                app.TextArea.Value = "Saving Data failed. Check if you made a selection of data components";
                pause(0.2);
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: RawDataButton
        function RawDataButtonValueChanged(app, event)
            % Check if Raw Data is part of the dataset. If yes, set
            % Whattosave(1,1) to 1, otherwise to 0
            value = app.RawDataButton.Value;
            if value == 1
                if ~isfield(app.Mainapp.Data,"Raw")
                    f = msgbox("No raw data found!");
                    app.RawDataButton.Value = 0;
                else
                    app.Whattosave(1,1) = 1;
                end
            else
                app.Whattosave(1,1) = 0;
            end
        end

        % Value changed function: PreprocessedDataButton
        function PreprocessedDataButtonValueChanged(app, event)
            % Check if Preprocessed Data is part of the dataset. If yes, set
            % Whattosave(1,2) to 1, otherwise to 0
            value = app.PreprocessedDataButton.Value;
            if value == 1
                if ~isfield(app.Mainapp.Data,"Preprocessed")
                    f = msgbox("No preprocessed data found!");
                    app.PreprocessedDataButton.Value = 0;
                else
                    app.Whattosave(1,2) = 1;
                end
            else
                app.Whattosave(1,2) = 0;
            end
        end

        % Value changed function: EventTimesButton
        function EventTimesButtonValueChanged(app, event)
            % Check if Event Data is part of the dataset. If yes, set
            % Whattosave(1,3) to 1, otherwise to 0
            value = app.EventTimesButton.Value;
            if value == 1
                if ~isfield(app.Mainapp.Data,"Events")
                    f = msgbox("No event data found!");
                    app.EventTimesButton.Value = 0;
                else
                    app.Whattosave(1,3) = 1;
                end
            else
                app.Whattosave(1,3) = 0;
            end
        end

        % Value changed function: SpikeIndiciesButton
        function SpikeIndiciesButtonValueChanged(app, event)
            % Check if Spike Data is part of the dataset. If yes, set
            % Whattosave(1,4) to 1, otherwise to 0
            value = app.SpikeIndiciesButton.Value;
            if value == 1
                if ~isfield(app.Mainapp.Data,"Spikes")
                    f = msgbox("No spike data found!");
                    app.SpikeIndiciesButton.Value = 0;
                else
                    app.Whattosave(1,4) = 1;
                end
            else
                app.Whattosave(1,4) = 0;
            end
        end

        % Value changed function: SelectAllButton
        function SelectAllButtonValueChanged(app, event)
            % Check existence of all data components and activate the ones
            % that are present 
            value = app.SelectAllButton.Value;
            if value == 1
                if isfield(app.Mainapp.Data,"Raw")
                    app.RawDataButton.Value = 1;
                    app.Whattosave(1,1) = 1;
                end
                if isfield(app.Mainapp.Data,"Preprocessed")
                    app.PreprocessedDataButton.Value = 1;
                    app.Whattosave(1,2) = 1;
                end
                if isfield(app.Mainapp.Data,"Events")
                    app.EventTimesButton.Value = 1;
                    app.Whattosave(1,3) = 1;
                end
                if isfield(app.Mainapp.Data,"Spikes")
                    app.SpikeIndiciesButton.Value = 1;
                    app.Whattosave(1,4) = 1;
                end
                if isfield(app.Mainapp.Data,"EventRelatedData")
                    app.EventRelatedDataButton.Value = 1;
                    app.Whattosave(1,5) = 1;
                end
                if isfield(app.Mainapp.Data,"PreprocessedEventRelatedData")
                    app.PreprocessedEventRelatedDataButton.Value = 1;
                    app.Whattosave(1,6) = 1;
                end

            else
                app.RawDataButton.Value = 0;
                app.PreprocessedDataButton.Value = 0;
                app.EventTimesButton.Value = 0;
                app.SpikeIndiciesButton.Value = 0;
                app.EventRelatedDataButton.Value = 0;
                app.Whattosave(1,1:6) = 0;
            end

            app.SelectAllButton.Value = 0;
        end

        % Value changed function: EventRelatedDataButton
        function EventRelatedDataButtonValueChanged(app, event)
            % Check if Event Related Data is part of the dataset. If yes, set
            % Whattosave(1,5) to 1, otherwise to 0
            value = app.EventRelatedDataButton.Value;
            if value == 1
                if ~isfield(app.Mainapp.Data,"EventRelatedData")
                    f = msgbox("No event related data data found!");
                    app.EventRelatedDataButton.Value = 0;
                else
                    app.Whattosave(1,5) = 1;
                end
            else
                app.Whattosave(1,5) = 0;
            end
        end

        % Value changed function: PreprocessedEventRelatedDataButton
        function PreprocessedEventRelatedDataButtonValueChanged(app, event)
            value = app.PreprocessedEventRelatedDataButton.Value;
            % Check if Event Data is part of the dataset. If yes, set
            % Whattosave(1,6) to 1, otherwise to 0
            if value == 1
                if ~isfield(app.Mainapp.Data,"PreprocessedEventRelatedData")
                    f = msgbox("No preprocessed event related data found!");
                    app.PreprocessedEventRelatedDataButton.Value = 0;
                else
                    app.Whattosave(1,6) = 1;
                end
            else
                app.Whattosave(1,6) = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SaveDataWindowUIFigure = uifigure('Visible', 'off');
            app.SaveDataWindowUIFigure.Name = 'Save Data Window';
            app.SaveDataWindowUIFigure.Icon = 'Logo.png';
            app.Panel = uipanel(app.SaveDataWindowUIFigure);
            app.RawDataButton = uibutton(app.Panel, 'state');
            app.RawDataButton.ValueChangedFcn = createCallbackFcn(app, @RawDataButtonValueChanged, true);
            app.RawDataButton.Text = 'Raw Data';
            app.PreprocessedDataButton = uibutton(app.Panel, 'state');
            app.PreprocessedDataButton.ValueChangedFcn = createCallbackFcn(app, @PreprocessedDataButtonValueChanged, true);
            app.PreprocessedDataButton.Text = 'Preprocessed Data';
            app.EventTimesButton = uibutton(app.Panel, 'state');
            app.EventTimesButton.ValueChangedFcn = createCallbackFcn(app, @EventTimesButtonValueChanged, true);
            app.EventTimesButton.Text = 'Event Times';
            app.SpikeIndiciesButton = uibutton(app.Panel, 'state');
            app.SpikeIndiciesButton.ValueChangedFcn = createCallbackFcn(app, @SpikeIndiciesButtonValueChanged, true);
            app.SpikeIndiciesButton.Text = 'Spike Indicies';
            app.WhattosaveLabel = uilabel(app.Panel);
            app.WhattosaveLabel.Text = 'What to save';
            app.EventRelatedDataButton = uibutton(app.Panel, 'state');
            app.EventRelatedDataButton.ValueChangedFcn = createCallbackFcn(app, @EventRelatedDataButtonValueChanged, true);
            app.EventRelatedDataButton.Text = 'Event Related Data';
            app.MultipleSelectionpossibleLabel = uilabel(app.Panel);
            app.MultipleSelectionpossibleLabel.Text = 'Multiple Selection possible';
            app.SaveasDropDownLabel = uilabel(app.Panel);
            app.SaveasDropDownLabel.Text = 'Save as';
            app.SaveTypeDropDown = uidropdown(app.Panel);
            app.SaveTypeDropDown.Items = {'.mat', '.dat'};
            app.SaveTypeDropDown.Value = '.dat';
            app.PreprocessedEventRelatedDataButton = uibutton(app.Panel, 'state');
            app.PreprocessedEventRelatedDataButton.ValueChangedFcn = createCallbackFcn(app, @PreprocessedEventRelatedDataButtonValueChanged, true);
            app.PreprocessedEventRelatedDataButton.Text = {'Preprocessed Event'; 'Related Data'};
            app.SelectAllButton = uibutton(app.Panel, 'state');
            app.SelectAllButton.ValueChangedFcn = createCallbackFcn(app, @SelectAllButtonValueChanged, true);
            app.SelectAllButton.Text = 'Select All';
            app.SaveDataWindowLabel = uilabel(app.SaveDataWindowUIFigure);
            app.SaveDataWindowLabel.Text = 'Save Data Window';
            app.Image = uiimage(app.SaveDataWindowUIFigure);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodToolboxLabel = uilabel(app.SaveDataWindowUIFigure);
            app.NeuromodToolboxLabel.Text = 'Neuromod Toolbox';
            app.TextArea = uitextarea(app.SaveDataWindowUIFigure);
            app.TextArea.Editable = 'off';
            app.CloseButton = uibutton(app.SaveDataWindowUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.SaveButton = uibutton(app.SaveDataWindowUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.InformationLabel = uilabel(app.SaveDataWindowUIFigure);
            app.InformationLabel.Text = 'Information';
            % Show the figure after all components are created
            app.SaveDataWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Save_Data_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SaveDataWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.SaveDataWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SaveDataWindowUIFigure)
        end
    end
end