classdef Change_Filter_Options_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ChangeFilterOptionsUIFigure  matlab.ui.Figure
        SetNewFilterSettingsButton   matlab.ui.control.Button
        ChangeFilterOptionsLabel     matlab.ui.control.Label
        CloseButton                  matlab.ui.control.Button
        ChangeFilterOptionsPanel     matlab.ui.container.Panel
        OrderLabel                   matlab.ui.control.Label
        CutOffLabel                  matlab.ui.control.Label
        CutoffFreuqencyEditField     matlab.ui.control.EditField
        FilterOrderEditField         matlab.ui.control.EditField
    end

    
    properties (Access = public)
        InstFreqApp = [];
        Mainapp = []; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, InstFreqApp, Mainapp, Filter)
            movegui(app.ChangeFilterOptionsUIFigure, 'north');
            
            app.Mainapp = Mainapp;
            app.InstFreqApp = InstFreqApp;
            
            % set up window components colors
            app = Utility_Change_Light_Dark_Mode(app,"AskForFilterSettingsInstFrequ");

            if strcmp(Filter,"NarrowBand")
                app.CutOffLabel.Text = 'NarrowBand Cutoff [from,to] in Hz';
                app.OrderLabel.Text = 'NarrowBand Filter Order';
            elseif strcmp(Filter,"LowPass")
                app.CutOffLabel.Text = 'Low-Pass Cutoff in Hz';
                app.OrderLabel.Text = 'Low-Pass Filter Order';
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            try
                app.InstFreqApp.ChangeFilterSettingsWindow = [];
            end
            
            delete(app)
        end

        % Close request function: ChangeFilterOptionsUIFigure
        function ChangeFilterOptionsUIFigureCloseRequest(app, event)
            try
                app.InstFreqApp.ChangeFilterSettingsWindow = [];
            end
            
            delete(app)
        end

        % Button pushed function: SetNewFilterSettingsButton
        function SetNewFilterSettingsButtonPushed(app, event)
            app.InstFreqApp.LowPassSettings.Cutoff = str2double(app.CutoffFreuqencyEditField.Value);
            app.InstFreqApp.LowPassSettings.FilterOrder = str2double(app.FilterOrderEditField.Value);

            %Resume execution to return the output
            uiresume(app.ChangeFilterOptionsUIFigure); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ChangeFilterOptionsUIFigure = uifigure('Visible', 'off');
            app.ChangeFilterOptionsUIFigure.Name = 'Change Filter Options';
            app.ChangeFilterOptionsUIFigure.Icon = 'Logo.png';
            app.ChangeFilterOptionsUIFigure.CloseRequestFcn = createCallbackFcn(app, @ChangeFilterOptionsUIFigureCloseRequest, true);
            app.ChangeFilterOptionsPanel = uipanel(app.ChangeFilterOptionsUIFigure);
            app.ChangeFilterOptionsPanel.Title = 'Change Filter Options';
            app.FilterOrderEditField = uieditfield(app.ChangeFilterOptionsPanel, 'text');
            app.CutoffFreuqencyEditField = uieditfield(app.ChangeFilterOptionsPanel, 'text');
            app.CutOffLabel = uilabel(app.ChangeFilterOptionsPanel);
            app.CutOffLabel.Text = 'CutOff';
            app.OrderLabel = uilabel(app.ChangeFilterOptionsPanel);
            app.OrderLabel.Text = 'Order';
            app.CloseButton = uibutton(app.ChangeFilterOptionsUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.ChangeFilterOptionsLabel = uilabel(app.ChangeFilterOptionsUIFigure);
            app.ChangeFilterOptionsLabel.Text = 'Change Filter Options';
            app.SetNewFilterSettingsButton = uibutton(app.ChangeFilterOptionsUIFigure, 'push');
            app.SetNewFilterSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SetNewFilterSettingsButtonPushed, true);
            app.SetNewFilterSettingsButton.Text = 'Set New Filter Settings';
            % Show the figure after all components are created
            app.ChangeFilterOptionsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Change_Filter_Options_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ChangeFilterOptionsUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ChangeFilterOptionsUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ChangeFilterOptionsUIFigure)
        end
    end
end