classdef CSD_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CSDWindowUIFigure          matlab.ui.Figure
        SnapshotMenu               matlab.ui.container.Menu
        SaveSnapshotofWindowMenu   matlab.ui.container.Menu
        PNGMenu                    matlab.ui.container.Menu
        JPGMenu                    matlab.ui.container.Menu
        PlotMenu                   matlab.ui.container.Menu
        Show2DimensionalPlotsMenu  matlab.ui.container.Menu
        Show3DimensionalPlotsMenu  matlab.ui.container.Menu
        ExportDataasMenu           matlab.ui.container.Menu
        matMenu                    matlab.ui.container.Menu
        txtMenu                    matlab.ui.container.Menu
        csvMenu                    matlab.ui.container.Menu
        CloseButton                matlab.ui.control.Button
        NeuromodToolboxLabel       matlab.ui.control.Label
        Image                      matlab.ui.control.Image
        LiveCSDWindowLabel         matlab.ui.control.Label
        Panel                      matlab.ui.container.Panel
        ChannelSelectionfromtoEditField  matlab.ui.control.EditField
        ChannelSelectionfromtoEditFieldLabel  matlab.ui.control.Label
        HammWindowEditField        matlab.ui.control.EditField
        HammWindowEditFieldLabel   matlab.ui.control.Label
        LockCLimCheckBox           matlab.ui.control.CheckBox
        UIAxes                     matlab.ui.control.UIAxes
        ContextMenu3               matlab.ui.container.ContextMenu
        ChangeTitleMenu            matlab.ui.container.Menu
        ChangeXLabelMenu           matlab.ui.container.Menu
        ChangeYLabelMenu           matlab.ui.container.Menu
        ChangeXlimMenu             matlab.ui.container.Menu
        ChangeYlimMenu             matlab.ui.container.Menu
        ChangeLegendMenu           matlab.ui.container.Menu
        ChangeFontsizeMenu         matlab.ui.container.Menu
    end

    
    properties (Access = public)
        Mainapp = []; % Description
        ExistflagCSD =  [];
        CSDClim = [];
        TwoORThreeD = "TwoD"; % standard 2d plots when possible
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            movegui(app.CSDWindowUIFigure, 'center');

            app.Mainapp = Mainapp;
            app.Mainapp.CSDApp = app;
            
            if ~isfield(app.Mainapp.Data,'Preprocessed') || ~isfield(app.Mainapp.Data.Info,'FilterMethod')
                msgbox("Error: No Preprocessed data found or data was not low pass filtered. Exiting");
                app.Mainapp.CSDApp = [];
                delete(app);
                return;
            end
            if isfield(app.Mainapp.Data.Info,'FilterMethod')
                if ~strcmp(app.Mainapp.Data.Info.FilterMethod,"Low-Pass")
                    msgbox("Error: No low pass filtered data found. Exiting");
                    app.Mainapp.CSDApp = [];
                    delete(app);
                    return;
                end
            end

            app.UIAxes.YDir = 'reverse';

           app.ChannelSelectionfromtoEditField.Value = strcat(num2str(1),",",num2str(size(app.Mainapp.Data.Preprocessed,1)));
           
           app.ExistflagCSD = 1;
        
           app.Mainapp.CSDApp = app;
           Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);

        end

        % Close request function: CSDWindowUIFigure
        function CSDWindowUIFigureCloseRequest(app, event)
            app.Mainapp.CSDApp = [];
            
            delete(app)
        end

        % Callback function
        function ChannelSpacingumEditFieldValueChanged(app, event)
           app.Mainapp.CSDApp = app;
           Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
        end

        % Callback function
        function HammWindowEditFieldValueChanged(app, event)
            app.Mainapp.CSDApp = app;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.Mainapp.TwoORThreeD = "TwoD";  
            app.Mainapp.CSDApp = [];
            app.ExistflagCSD = 0;
            delete(app)
        end

        % Callback function
        function ChannelSelectionfromtoEditFieldValueChanged(app, event)
            app.Mainapp.CSDApp = app;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
        end

        % Value changed function: HammWindowEditField
        function HammWindowEditFieldValueChanged2(app, event)
            [app.HammWindowEditField.Value] = Utility_SimpleCheckInputs(app.HammWindowEditField.Value,"One",'5',0,0);

            app.Mainapp.CSDApp = app;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            
        end

        % Value changed function: ChannelSelectionfromtoEditField
        function ChannelSelectionfromtoEditFieldValueChanged2(app, event)
            if isfield(app.Mainapp.Data,"Preprocessed")
                [app.ChannelSelectionfromtoEditField.Value] = Utility_SimpleCheckInputs(app.ChannelSelectionfromtoEditField.Value,"Two",strcat('1,',num2str(size(app.Mainapp.Data.Preprocessed,1))),1,0);
           else
                [app.ChannelSelectionfromtoEditField.Value] = Utility_SimpleCheckInputs(app.ChannelSelectionfromtoEditField.Value,"Two",strcat('1,',num2str(size(app.Mainapp.Data.Raw,1))),1,0);
            end

            app.Mainapp.CSDApp = app;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
        end

        % Value changed function: LockCLimCheckBox
        function LockCLimCheckBoxValueChanged(app, event)
                        
        end

        % Menu selected function: PNGMenu
        function PNGMenuSelected(app, event)
            [file, path] = uiputfile('*.png', 'Save as');

            exportapp(app.CSDWindowUIFigure,fullfile(path,file)) 
        end

        % Menu selected function: JPGMenu
        function JPGMenuSelected(app, event)
            [file, path] = uiputfile('*.jpg', 'Save as');

            exportapp(app.CSDWindowUIFigure,fullfile(path,file)) 
        end

        % Menu selected function: ChangeTitleMenu
        function ChangeTitleMenuSelected(app, event)
            Figure_Property_Change_Window(app.UIAxes,'title');
        end

        % Menu selected function: ChangeXLabelMenu
        function ChangeXLabelMenuSelected(app, event)
            Figure_Property_Change_Window(app.UIAxes,'xlabel');
        end

        % Menu selected function: ChangeYLabelMenu
        function ChangeYLabelMenuSelected(app, event)
            Figure_Property_Change_Window(app.UIAxes,'ylabel');
        end

        % Menu selected function: ChangeXlimMenu
        function ChangeXlimMenuSelected(app, event)
            Figure_Property_Change_Window(app.UIAxes,'xlim');
        end

        % Menu selected function: ChangeYlimMenu
        function ChangeYlimMenuSelected(app, event)
            Figure_Property_Change_Window(app.UIAxes,'ylim');
        end

        % Menu selected function: ChangeLegendMenu
        function ChangeLegendMenuSelected(app, event)
            Figure_Property_Change_Window(app.UIAxes,'legend');
        end

        % Menu selected function: ChangeFontsizeMenu
        function ChangeFontsizeMenuSelected(app, event)
            
        end

        % Menu selected function: Show2DimensionalPlotsMenu
        function Show2DimensionalPlotsMenuSelected(app, event)
            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";
            
            app.TwoORThreeD = "TwoD";

            app.Mainapp.CSDApp = app;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
        end

        % Menu selected function: matMenu
        function matMenuSelected(app, event)
            TimeRangePlot = str2double(app.Mainapp.TimeRangeViewBox.Value(1:end-1));
            if app.Mainapp.PreprocDataPlotCheckBox.Value == 1
                if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                else
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                end
            else
                StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
            end

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Live_CSD_Main_Window");
        end

        % Menu selected function: txtMenu
        function txtMenuSelected(app, event)
            TimeRangePlot = str2double(app.Mainapp.TimeRangeViewBox.Value(1:end-1));
            if app.Mainapp.PreprocDataPlotCheckBox.Value == 1
                if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                else
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                end
            else
                StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
            end

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Live_CSD_Main_Window");
        end

        % Menu selected function: csvMenu
        function csvMenuSelected(app, event)
            TimeRangePlot = str2double(app.Mainapp.TimeRangeViewBox.Value(1:end-1));
            if app.Mainapp.PreprocDataPlotCheckBox.Value == 1
                if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                else
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                end
            else
                StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
            end

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".csv",app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Live_CSD_Main_Window");
        end

        % Menu selected function: Show3DimensionalPlotsMenu
        function Show3DimensionalPlotsMenuSelected(app, event)
            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";
            
            app.TwoORThreeD = "ThreeD";

            app.Mainapp.CSDApp = app;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CSDWindowUIFigure = uifigure('Visible', 'off');
            app.CSDWindowUIFigure.Name = 'CSD Window';
            app.CSDWindowUIFigure.Icon = 'Logo.png';
            app.CSDWindowUIFigure.CloseRequestFcn = createCallbackFcn(app, @CSDWindowUIFigureCloseRequest, true);
            app.SnapshotMenu = uimenu(app.CSDWindowUIFigure);
            app.SnapshotMenu.Text = '   Snapshot   ';
            app.SaveSnapshotofWindowMenu = uimenu(app.SnapshotMenu);
            app.SaveSnapshotofWindowMenu.Text = 'Save Snapshot of Window';
            app.PNGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.PNGMenu.MenuSelectedFcn = createCallbackFcn(app, @PNGMenuSelected, true);
            app.PNGMenu.Text = 'PNG';
            app.JPGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.JPGMenu.MenuSelectedFcn = createCallbackFcn(app, @JPGMenuSelected, true);
            app.JPGMenu.Text = 'JPG';
            app.PlotMenu = uimenu(app.CSDWindowUIFigure);
            app.PlotMenu.Text = '   Plot   ';
            app.Show2DimensionalPlotsMenu = uimenu(app.PlotMenu);
            app.Show2DimensionalPlotsMenu.MenuSelectedFcn = createCallbackFcn(app, @Show2DimensionalPlotsMenuSelected, true);
            app.Show2DimensionalPlotsMenu.Text = 'Show 2 Dimensional Plots';
            app.Show3DimensionalPlotsMenu = uimenu(app.PlotMenu);
            app.Show3DimensionalPlotsMenu.MenuSelectedFcn = createCallbackFcn(app, @Show3DimensionalPlotsMenuSelected, true);
            app.Show3DimensionalPlotsMenu.Text = 'Show 3 Dimensional Plots';
            app.ExportDataasMenu = uimenu(app.CSDWindowUIFigure);
            app.ExportDataasMenu.Text = '   Export Data as   ';
            app.matMenu = uimenu(app.ExportDataasMenu);
            app.matMenu.MenuSelectedFcn = createCallbackFcn(app, @matMenuSelected, true);
            app.matMenu.Text = '.mat';
            app.txtMenu = uimenu(app.ExportDataasMenu);
            app.txtMenu.MenuSelectedFcn = createCallbackFcn(app, @txtMenuSelected, true);
            app.txtMenu.Text = '.txt';
            app.csvMenu = uimenu(app.ExportDataasMenu);
            app.csvMenu.MenuSelectedFcn = createCallbackFcn(app, @csvMenuSelected, true);
            app.csvMenu.Text = '.csv';
            app.UIAxes = uiaxes(app.CSDWindowUIFigure);
            app.Panel = uipanel(app.CSDWindowUIFigure);
            app.LockCLimCheckBox = uicheckbox(app.Panel);
            app.LockCLimCheckBox.ValueChangedFcn = createCallbackFcn(app, @LockCLimCheckBoxValueChanged, true);
            app.LockCLimCheckBox.Text = '  Lock C Lim';
            app.LockCLimCheckBox.Value = true;
            app.HammWindowEditFieldLabel = uilabel(app.Panel);
            app.HammWindowEditFieldLabel.Text = 'Hamm Window';
            app.HammWindowEditField = uieditfield(app.Panel, 'text');
            app.HammWindowEditField.ValueChangedFcn = createCallbackFcn(app, @HammWindowEditFieldValueChanged2, true);
            app.HammWindowEditField.Value = '5';
            app.ChannelSelectionfromtoEditFieldLabel = uilabel(app.Panel);
            app.ChannelSelectionfromtoEditFieldLabel.Text = {'Channel Selection'; '[from,to]'};
            app.ChannelSelectionfromtoEditField = uieditfield(app.Panel, 'text');
            app.ChannelSelectionfromtoEditField.ValueChangedFcn = createCallbackFcn(app, @ChannelSelectionfromtoEditFieldValueChanged2, true);
            app.LiveCSDWindowLabel = uilabel(app.CSDWindowUIFigure);
            app.LiveCSDWindowLabel.Text = 'Live CSD Window';
            app.Image = uiimage(app.CSDWindowUIFigure);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodToolboxLabel = uilabel(app.CSDWindowUIFigure);
            app.NeuromodToolboxLabel.Text = 'Neuromod Toolbox';
            app.CloseButton = uibutton(app.CSDWindowUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.ContextMenu3 = uicontextmenu(app.CSDWindowUIFigure);
            app.ChangeTitleMenu = uimenu(app.ContextMenu3);
            app.ChangeTitleMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeTitleMenuSelected, true);
            app.ChangeTitleMenu.Text = 'Change Title';
            app.ChangeXLabelMenu = uimenu(app.ContextMenu3);
            app.ChangeXLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeXLabelMenuSelected, true);
            app.ChangeXLabelMenu.Text = 'Change X Label';
            app.ChangeYLabelMenu = uimenu(app.ContextMenu3);
            app.ChangeYLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeYLabelMenuSelected, true);
            app.ChangeYLabelMenu.Text = 'Change Y Label';
            app.ChangeXlimMenu = uimenu(app.ContextMenu3);
            app.ChangeXlimMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeXlimMenuSelected, true);
            app.ChangeXlimMenu.Text = 'Change Xlim';
            app.ChangeYlimMenu = uimenu(app.ContextMenu3);
            app.ChangeYlimMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeYlimMenuSelected, true);
            app.ChangeYlimMenu.Text = 'Change Ylim';
            app.ChangeLegendMenu = uimenu(app.ContextMenu3);
            app.ChangeLegendMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeLegendMenuSelected, true);
            app.ChangeLegendMenu.Text = 'Change Legend';
            app.ChangeFontsizeMenu = uimenu(app.ContextMenu3);
            app.ChangeFontsizeMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeFontsizeMenuSelected, true);
            app.ChangeFontsizeMenu.Text = 'Change Fontsize';
            % Assign app.ContextMenu3
            app.UIAxes.ContextMenu = app.ContextMenu3;
            % Show the figure after all components are created
            app.CSDWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CSD_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.CSDWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.CSDWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CSDWindowUIFigure)
        end
    end
end