classdef ECHT_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EndpointCorrectedHilbertTransformUIFigure  matlab.ui.Figure
        SettingsPanel                   matlab.ui.container.Panel
        LockYlimCheckBox                matlab.ui.control.CheckBox
        NarrowbandFilterorderEditField  matlab.ui.control.EditField
        NarrowbandFilterorderLabel      matlab.ui.control.Label
        NarrowbandCutoffLowerHigherEditField  matlab.ui.control.EditField
        NarrowbandCutoffLowerHigherEditFieldLabel  matlab.ui.control.Label
        CloseButton                     matlab.ui.control.Button
        ChannelSelectionDropDown        matlab.ui.control.DropDown
        ChannelSelectionDropDownLabel   matlab.ui.control.Label
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Mainapp = []; % Description
        ExistflagECHT = [];
        Startup = 1
        GlobalYlim = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            
            movegui(app.EndpointCorrectedHilbertTransformUIFigure, 'north');
            
            app.Mainapp = Mainapp;

            if ~isfield(app.Mainapp.Data,'Raw') 
                msgbox("Error: No data detected. Please first extract data from a recording!");
                app.Mainapp.LiveECHTWindow = [];
                delete(app);
            end
                        
            freqs = str2double(strsplit(app.NarrowbandCutoffLowerHigherEditField.Value,','));
            app.NarrowbandFilterorderEditField.Value = num2str(round( 4*1000/freqs(1) ));

            app.ExistflagECHT = 1;
            
            app.Mainapp.LiveECHTWindow = app;
           
            for i = 1:size(app.Mainapp.Data.Raw,1)
                app.ChannelSelectionDropDown.Items{i} = num2str(i);
            end
            
            xlabel(app.UIAxes,'Time (s)');
            ylabel(app.UIAxes,'Phase (rad)');
            title(app.UIAxes,"Instantaneous Frequency [Hz]");

            xlabel(app.UIAxes_2,'Time (s)');
            ylabel(app.UIAxes_2,'Frequency [Hz]');
            title(app.UIAxes_2,"Instantaneous Frequency [Hz]")
            
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);

            legend(app.UIAxes,'Endpoint Corr. HT','Hilbert Transform')
            app.Startup = 0;
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            try
                app.ExistflagECHT = 0;
                app.Mainapp.LiveECHTWindow = [];
                delete(app)
            catch
                delete(app)
            end
        end

        % Close request function: EndpointCorrectedHilbertTransformUIFigure
        function EndpointCorrectedHilbertTransformUIFigureCloseRequest(app, event)
            try
                app.ExistflagECHT = 0;
                app.Mainapp.LiveECHTWindow = [];
                delete(app)
            catch
                delete(app)
            end
        end

        % Value changed function: ChannelSelectionDropDown
        function ChannelSelectionDropDownValueChanged(app, event)
           
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
        end

        % Value changed function: NarrowbandCutoffLowerHigherEditField
        function NarrowbandCutoffLowerHigherEditFieldValueChanged(app, event)
            freqs = str2double(strsplit(app.NarrowbandCutoffLowerHigherEditField.Value,','));
            app.NarrowbandFilterorderEditField.Value = num2str(round( 4*1000/freqs(1) ));


            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
        end

        % Value changed function: NarrowbandFilterorderEditField
        function NarrowbandFilterorderEditFieldValueChanged(app, event)
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EndpointCorrectedHilbertTransformUIFigure = uifigure('Visible', 'off');
            app.EndpointCorrectedHilbertTransformUIFigure.Name = 'Endpoint Corrected Hilbert Transform';
            app.EndpointCorrectedHilbertTransformUIFigure.Icon = 'Logo.png';
            app.EndpointCorrectedHilbertTransformUIFigure.CloseRequestFcn = createCallbackFcn(app, @EndpointCorrectedHilbertTransformUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.EndpointCorrectedHilbertTransformUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.EndpointCorrectedHilbertTransformUIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.SettingsPanel = uipanel(app.EndpointCorrectedHilbertTransformUIFigure);
            app.SettingsPanel.Title = 'Settings';
            app.ChannelSelectionDropDownLabel = uilabel(app.SettingsPanel);
            app.ChannelSelectionDropDownLabel.Text = 'Channel Selection';
            app.ChannelSelectionDropDown = uidropdown(app.SettingsPanel);
            app.ChannelSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelSelectionDropDownValueChanged, true);
            app.CloseButton = uibutton(app.SettingsPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.NarrowbandCutoffLowerHigherEditFieldLabel = uilabel(app.SettingsPanel);
            app.NarrowbandCutoffLowerHigherEditFieldLabel.Text = {'Narrowband Cutoff'; '[Lower,Higher]'};
            app.NarrowbandCutoffLowerHigherEditField = uieditfield(app.SettingsPanel, 'text');
            app.NarrowbandCutoffLowerHigherEditField.ValueChangedFcn = createCallbackFcn(app, @NarrowbandCutoffLowerHigherEditFieldValueChanged, true);
            app.NarrowbandCutoffLowerHigherEditField.Value = '12,16';
            app.NarrowbandFilterorderLabel = uilabel(app.SettingsPanel);
            app.NarrowbandFilterorderLabel.Text = {'Narrowband'; 'Filterorder'};
            app.NarrowbandFilterorderEditField = uieditfield(app.SettingsPanel, 'text');
            app.NarrowbandFilterorderEditField.ValueChangedFcn = createCallbackFcn(app, @NarrowbandFilterorderEditFieldValueChanged, true);
            app.NarrowbandFilterorderEditField.Value = '250';
            app.LockYlimCheckBox = uicheckbox(app.SettingsPanel);
            app.LockYlimCheckBox.Text = '   Lock Ylim';
            app.LockYlimCheckBox.Value = true;
            % Show the figure after all components are created
            app.EndpointCorrectedHilbertTransformUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ECHT_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.EndpointCorrectedHilbertTransformUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.EndpointCorrectedHilbertTransformUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EndpointCorrectedHilbertTransformUIFigure)
        end
    end
end