classdef Live_InstFrequency_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InstantaneousFrequencyUIFigure  matlab.ui.Figure
        CloseButton                     matlab.ui.control.Button
        InformationTextArea             matlab.ui.control.TextArea
        InformationTextAreaLabel        matlab.ui.control.Label
        SettingsPanel                   matlab.ui.container.Panel
        LockYlimCheckBox                matlab.ui.control.CheckBox
        NarrowbandFilterorderEditField  matlab.ui.control.EditField
        NarrowbandFilterorderLabel      matlab.ui.control.Label
        NarrowbandCutoffLowerHigherEditField  matlab.ui.control.EditField
        NarrowbandCutoffLowerHigherEditFieldLabel  matlab.ui.control.Label
        ChannelSelectionDropDown        matlab.ui.control.DropDown
        ChannelSelectionDropDownLabel   matlab.ui.control.Label
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Mainapp = []; % Description
        ExistflagECHT = [];
        Startup = 1
        GlobalYlim = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            
            movegui(app.InstantaneousFrequencyUIFigure, 'north');
            
            app.Mainapp = Mainapp;

            app = Utility_Change_Light_Dark_Mode(app,"InstFrequWindow");

            if ~isfield(app.Mainapp.Data,'Raw') 
                msgbox("Error: No data detected. Please first extract data from a recording!");
                app.Mainapp.LiveECHTWindow = [];
                delete(app);
            end
            
            NBF = 0;
            DS = 0;
            if isfield(app.Mainapp.Data.Info,'DownsampledSampleRate')
                DS = 1;
            end

            if isfield(app.Mainapp.Data.Info,'NarrowbandFilterMethod')
                NBF = 1;
                
            end
            if strcmp(app.Mainapp.DropDown.Value,"Raw Data")
                app.InformationTextArea.Value = "Raw Data selected in the main window. Signal is automatically low pass filtered, downsampled and narrowband filtered.";
                if isfield(app.Mainapp.Data,'Preprocessed')
                    if DS == 1 && NBF == 1
                        app.InformationTextArea.Value = [app.InformationTextArea.Value;"Preprocessed signal with narrowband filter and downsampling found. Change the main window plot to show phase analysis of preprocessed data without additional low pass filter and downsampling."];
                    elseif DS == 1 && NBF == 0
                        app.InformationTextArea.Value = [app.InformationTextArea.Value;"Preprocessed signal without narrowband filter and with downsampling found. When changing the main plot to show preprocessed data, data will be narrowband filtered for this analysis. This leads to alisasing. To avoid this, first low pass filter the signal before downsampling!"];
                    elseif DS == 0 && NBF == 1
                        app.InformationTextArea.Value = [app.InformationTextArea.Value;"Preprocessed signal with narrowband filter and without downsampling found. Change the main window plot to show phase analysis of preprocessed data with additional downsampling."];
                    elseif DS == 0 && NBF == 0
                        app.InformationTextArea.Value = [app.InformationTextArea.Value;"Preprocessed signal without narrowband filter and without downsampling found. Change the main window plot to show phase analysis of preprocessed data with additional low pass filtere and downsample step applied."];
                    end
                end
            else
                if DS == 1 && NBF == 1
                    app.InformationTextArea.Value = "Preprocessed signal with narrowband filter and downsampling found. Phase analysis of preprocessed data is conducted without additional low pass filter and downsampling.";
                elseif DS == 1 && NBF == 0
                    app.InformationTextArea.Value = "Preprocessed signal without narrowband filter and with downsampling found. Data will be narrowband filtered for this analysis, leading to alisasing. To avoid this, first low pass filter the signal before downsampling!";
                elseif DS == 0 && NBF == 1
                    app.InformationTextArea.Value = "Preprocessed signal with narrowband filter and without downsampling found. Phase analysis of preprocessed data is conducted with additional downsampling.";
                elseif DS == 0 && NBF == 0
                    app.InformationTextArea.Value = "Preprocessed signal without narrowband filter and without downsampling found. Phase analysis of preprocessed data is conducted with an additional low pass filter and downsampling step being applied.";
                end
            end
                        
            freqs = str2double(strsplit(app.NarrowbandCutoffLowerHigherEditField.Value,','));
            app.NarrowbandFilterorderEditField.Value = num2str(round( 4*1000/freqs(1) ));

            app.ExistflagECHT = 1;
            
            app.Mainapp.LiveECHTWindow = app;
            AllChannels = {};
            if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 1
                TempActiveChannel = (str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)*str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)+1)-sort(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
                for i = 1:length(TempActiveChannel)
                    AllChannels{i} = num2str(TempActiveChannel(i));
                end
            else
                for i = 1:length(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel)
                    AllChannels{i} = num2str(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel(i));
                end
            end

            app.ChannelSelectionDropDown.Items = AllChannels;
            
            xlabel(app.UIAxes,'Time (s)');
            ylabel(app.UIAxes,'Phase (rad)');
            title(app.UIAxes,"Instantaneous Phase");

            xlabel(app.UIAxes_2,'Time (s)');
            ylabel(app.UIAxes_2,'Frequency [Hz]');
            title(app.UIAxes_2,"Instantaneous Frequency")
            
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);

            legend(app.UIAxes,'Endpoint Corr. HT','Hilbert Transform')
            app.Startup = 0;
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            try
                app.ExistflagECHT = 0;
                app.Mainapp.LiveECHTWindow = [];
                delete(app)
            catch
                delete(app)
            end
        end

        % Close request function: InstantaneousFrequencyUIFigure
        function InstantaneousFrequencyUIFigureCloseRequest(app, event)
            try
                app.ExistflagECHT = 0;
                app.Mainapp.LiveECHTWindow = [];
                delete(app)
            catch
                delete(app)
            end
        end

        % Value changed function: ChannelSelectionDropDown
        function ChannelSelectionDropDownValueChanged(app, event)
           
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
        end

        % Value changed function: NarrowbandCutoffLowerHigherEditField
        function NarrowbandCutoffLowerHigherEditFieldValueChanged(app, event)
            freqs = str2double(strsplit(app.NarrowbandCutoffLowerHigherEditField.Value,','));
            app.NarrowbandFilterorderEditField.Value = num2str(round( 4*1000/freqs(1) ));


            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
        end

        % Value changed function: NarrowbandFilterorderEditField
        function NarrowbandFilterorderEditFieldValueChanged(app, event)
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InstantaneousFrequencyUIFigure = uifigure('Visible', 'off');
            app.InstantaneousFrequencyUIFigure.Name = 'Instantaneous Frequency';
            app.InstantaneousFrequencyUIFigure.Icon = 'Logo.png';
            app.InstantaneousFrequencyUIFigure.CloseRequestFcn = createCallbackFcn(app, @InstantaneousFrequencyUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.InstantaneousFrequencyUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.InstantaneousFrequencyUIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.SettingsPanel = uipanel(app.InstantaneousFrequencyUIFigure);
            app.SettingsPanel.Title = 'Settings';
            app.ChannelSelectionDropDownLabel = uilabel(app.SettingsPanel);
            app.ChannelSelectionDropDownLabel.Text = 'Channel Selection';
            app.ChannelSelectionDropDown = uidropdown(app.SettingsPanel);
            app.ChannelSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelSelectionDropDownValueChanged, true);
            app.NarrowbandCutoffLowerHigherEditFieldLabel = uilabel(app.SettingsPanel);
            app.NarrowbandCutoffLowerHigherEditFieldLabel.Text = {'Narrowband Cutoff'; '[Lower,Higher]'};
            app.NarrowbandCutoffLowerHigherEditField = uieditfield(app.SettingsPanel, 'text');
            app.NarrowbandCutoffLowerHigherEditField.ValueChangedFcn = createCallbackFcn(app, @NarrowbandCutoffLowerHigherEditFieldValueChanged, true);
            app.NarrowbandCutoffLowerHigherEditField.Value = '12,16';
            app.NarrowbandFilterorderLabel = uilabel(app.SettingsPanel);
            app.NarrowbandFilterorderLabel.Text = {'Narrowband'; 'Filterorder'};
            app.NarrowbandFilterorderEditField = uieditfield(app.SettingsPanel, 'text');
            app.NarrowbandFilterorderEditField.ValueChangedFcn = createCallbackFcn(app, @NarrowbandFilterorderEditFieldValueChanged, true);
            app.NarrowbandFilterorderEditField.Value = '4';
            app.LockYlimCheckBox = uicheckbox(app.SettingsPanel);
            app.LockYlimCheckBox.Text = '   Lock Ylim';
            app.LockYlimCheckBox.Value = true;
            app.InformationTextAreaLabel = uilabel(app.InstantaneousFrequencyUIFigure);
            app.InformationTextAreaLabel.Text = 'Information';
            app.InformationTextArea = uitextarea(app.InstantaneousFrequencyUIFigure);
            app.CloseButton = uibutton(app.InstantaneousFrequencyUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.InstantaneousFrequencyUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Live_InstFrequency_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.InstantaneousFrequencyUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.InstantaneousFrequencyUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InstantaneousFrequencyUIFigure)
        end
    end
end