classdef Main_Plot_Phase_Synchro < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PhaseSynchronizationWindowUIFigure  matlab.ui.Figure
        SnapshotMenu                    matlab.ui.container.Menu
        SaveSnapshotofWindowMenu        matlab.ui.container.Menu
        PNGMenu                         matlab.ui.container.Menu
        JPGMenu                         matlab.ui.container.Menu
        PlotMenu                        matlab.ui.container.Menu
        ChangePlotPropertiesMenu        matlab.ui.container.Menu
        AlltoAllSyncPlotMenu            matlab.ui.container.Menu
        FontSizeMenu                    matlab.ui.container.Menu
        XLabelMenu                      matlab.ui.container.Menu
        YLabelMenu                      matlab.ui.container.Menu
        CLabelMenu                      matlab.ui.container.Menu
        TitleMenu                       matlab.ui.container.Menu
        RESETMenu                       matlab.ui.container.Menu
        PhaseAngleDifferencePlotMenu_2  matlab.ui.container.Menu
        FontSizeMenu_2                  matlab.ui.container.Menu
        ColorPhaseAngleDifferencesMenu  matlab.ui.container.Menu
        ColorAveragePhaseVectorMenu     matlab.ui.container.Menu
        LineWidthPhaseAngleDifferencesMenu  matlab.ui.container.Menu
        LineWidthAveragePhaseVectorMenu  matlab.ui.container.Menu
        BackgorundColorMenu_2           matlab.ui.container.Menu
        TitleMenu_2                     matlab.ui.container.Menu
        RESETMenu_2                     matlab.ui.container.Menu
        SaveCostumeAppearanceMenu       matlab.ui.container.Menu
        DeleteCostumeAppearanceMenu     matlab.ui.container.Menu
        ExportDataMenu                  matlab.ui.container.Menu
        matMenu                         matlab.ui.container.Menu
        txtMenu                         matlab.ui.container.Menu
        csvMenu                         matlab.ui.container.Menu
        InformationTextArea             matlab.ui.control.TextArea
        InformationTextAreaLabel        matlab.ui.control.Label
        CloseButton                     matlab.ui.control.Button
        ChannelSelectionLabel           matlab.ui.control.Label
        FilterParameterLabel            matlab.ui.control.Label
        FilterParameterPanel            matlab.ui.container.Panel
        NarrowbandFilterorderEditField  matlab.ui.control.EditField
        NarrowbandFilterorderEditFieldLabel  matlab.ui.control.Label
        MinMaxFrequencylowerhigherinHzEditField  matlab.ui.control.EditField
        NarrLabel                       matlab.ui.control.Label
        ChannelSelectionPanel           matlab.ui.container.Panel
        Channel2DropDown                matlab.ui.control.DropDown
        Channel2DropDownLabel           matlab.ui.control.Label
        Channel1DropDown                matlab.ui.control.DropDown
        Channel1DropDownLabel           matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        UIAxes_2                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Mainapp = []; % Description
        ChannelToCompare = [];
        Startup = [];
        ExistflagPhaseSync = [];
        PolarPlot = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            
            movegui(app.PhaseSynchronizationWindowUIFigure, 'north');
        
            app.Mainapp = Mainapp;
            
            if ~isfield(app.Mainapp.Data,'Raw') 
                msgbox("Error: No data detected. Please first extract data from a recording!");
                app.Mainapp.LiveECHTWindow = [];
                delete(app);
            end

            app = Utility_Change_Light_Dark_Mode(app,"Phase_Synchro_Window");
            
            app.PolarPlot = polaraxes('Parent', app.Panel);
            
            app.InformationTextArea.Value = Utility_Show_Narrowband_FilterStatusMessage(app.Mainapp.Data,app.InformationTextArea.Value,app.Mainapp.DropDown.Value);

            app.Startup = 1;
            app.ExistflagPhaseSync = 1;
            % Populate dropdowns channel selection
            
            AllChannels = {};
            N = size(app.Mainapp.Data.Raw,1);  

            if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 1
                TempActiveChannel = (str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)*str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)+1)-sort(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
                for i = 1:N
                    AllChannels{i} = num2str(TempActiveChannel(i));
                end
            else
                for i = 1:N
                    AllChannels{i} = num2str(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel(i));
                end
            end

            app.Channel1DropDown.Items = AllChannels;
            app.Channel2DropDown.Items = AllChannels;
            app.Channel2DropDown.Value = app.Channel2DropDown.Items{2};
            
            freqs = str2double(strsplit(app.MinMaxFrequencylowerhigherinHzEditField.Value,','));
            [app.NarrowbandFilterorderEditField.Value] = Utility_Determine_FIR_FilterOrder(app,freqs(1));

            app.ChannelToCompare = [str2double(app.Channel1DropDown.Value),str2double(app.Channel2DropDown.Value)];
            
            app.Mainapp.PhaseSyncApp = app;

            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            
            
        end

        % Close request function: PhaseSynchronizationWindowUIFigure
        function PhaseSynchronizationWindowUIFigureCloseRequest(app, event)
            try
                app.Mainapp.PhaseSyncApp = [];
                delete(app)
            catch
                delete(app)
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            try
                app.Mainapp.PhaseSyncApp = [];
                delete(app)
            catch
                delete(app)
            end
        end

        % Value changed function: MinMaxFrequencylowerhigherinHzEditField
        function MinMaxFrequencylowerhigherinHzEditFieldValueChanged(app, event)
            freqs = str2double(strsplit(app.MinMaxFrequencylowerhigherinHzEditField.Value,','));
            [app.NarrowbandFilterorderEditField.Value] = Utility_Determine_FIR_FilterOrder(app,freqs(1));

            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
        end

        % Value changed function: Channel1DropDown
        function Channel1DropDownValueChanged(app, event)

            app.ChannelToCompare = [str2double(app.Channel1DropDown.Value),str2double(app.Channel2DropDown.Value)];

            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
        end

        % Value changed function: Channel2DropDown
        function Channel2DropDownValueChanged(app, event)
            app.ChannelToCompare = [str2double(app.Channel1DropDown.Value),str2double(app.Channel2DropDown.Value)];

            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
        end

        % Value changed function: NarrowbandFilterorderEditField
        function NarrowbandFilterorderEditFieldValueChanged(app, event)

            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
        end

        % Menu selected function: PNGMenu
        function PNGMenuSelected(app, event)
            [file, path] = uiputfile('*.png', 'Save as');

            exportapp(app.PhaseSynchronizationWindowUIFigure,fullfile(path,file)) 
        end

        % Menu selected function: JPGMenu
        function JPGMenuSelected(app, event)
            [file, path] = uiputfile('*.jpg', 'Save as');

            exportapp(app.PhaseSynchronizationWindowUIFigure,fullfile(path,file)) 
        end

        % Menu selected function: matMenu
        function matMenuSelected(app, event)
            % TimeRangePlot = str2double(app.Mainapp.TimeRangeViewBox.Value(1:end-1));
            % if strcmp(app.Mainapp.DropDown.Value,"Preprocessed Data")
            %     if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
            %         StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
            %     else
            %         StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
            %     end
            % else
            %     StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
            % end
            % 
            % Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Live_Phase_Sync_Window");
        end

        % Menu selected function: FontSizeMenu
        function FontSizeMenuSelected(app, event)
            
            h = Figure_Property_Change_Window(app.UIAxes_2,'Font Size',app.Mainapp,"MainDataphasesyncAllFontSize");
            waitfor(h);
            
            cla(app.UIAxes_2)
            cla(app.PolarPlot)

            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
        end

        % Menu selected function: XLabelMenu
        function XLabelMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes_2,'X Label',app.Mainapp,"MainDataphasesyncAllXLabel");
            waitfor(h);
            
            cla(app.UIAxes_2)
            cla(app.PolarPlot)

            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
        end

        % Menu selected function: YLabelMenu
        function YLabelMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes_2,'Y Label',app.Mainapp,"MainDataphasesyncAllYLabel");
            waitfor(h);
            
            cla(app.UIAxes_2)
            cla(app.PolarPlot)

            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
        end

        % Menu selected function: TitleMenu
        function TitleMenuSelected(app, event)
          
            h = Figure_Property_Change_Window(app.UIAxes_2,'Title',app.Mainapp,"MainDataphasesyncAllTitle");
            waitfor(h);

            cla(app.UIAxes_2)
            cla(app.PolarPlot)
            
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
        end

        % Menu selected function: CLabelMenu
        function CLabelMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes_2,'C Label',app.Mainapp,"MainDataphasesyncCLabel");
            waitfor(h);

            cla(app.UIAxes_2)
            cla(app.PolarPlot)
            
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
        end

        % Menu selected function: FontSizeMenu_2
        function FontSizeMenu_2Selected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes_2,'Font Size',app.Mainapp,"MainDataphasesyncDiffsFontSize");
            waitfor(h);
            
            cla(app.UIAxes_2)
            cla(app.PolarPlot)

            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
        end

        % Menu selected function: TitleMenu_2
        function TitleMenu_2Selected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes_2,'Title',app.Mainapp,"MainDataphasesyncDiffsTitle");
            waitfor(h);
            
            cla(app.UIAxes_2)
            cla(app.PolarPlot)

            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
        end

        % Menu selected function: BackgorundColorMenu_2
        function BackgorundColorMenu_2Selected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.Mainapp.PlotAppearance.PhaseSyncPlots.AngleDiffBackgroundColor = selectedColor;
                app.PolarPlot.Color = app.Mainapp.PlotAppearance.PhaseSyncPlots.AngleDiffBackgroundColor;
            end
        end

        % Menu selected function: RESETMenu
        function RESETMenuSelected(app, event)
            [app.Mainapp.PlotAppearance] = Organize_Set_Standard_PlotAppearance("PhaseSyncPlotAllToAll",app.Mainapp.PlotAppearance);
            
            cla(app.UIAxes_2)
            cla(app.PolarPlot)

            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
        end

        % Menu selected function: ColorPhaseAngleDifferencesMenu
        function ColorPhaseAngleDifferencesMenuSelected(app, event)
            cla(app.UIAxes_2)
            cla(app.PolarPlot)

            selectedColor = uisetcolor; 
            
            if length(selectedColor) == 3
                app.Mainapp.PlotAppearance.PhaseSyncPlots.AngleDiffAnglesColor = selectedColor;   
            end

            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);

        end

        % Menu selected function: ColorAveragePhaseVectorMenu
        function ColorAveragePhaseVectorMenuSelected(app, event)
            cla(app.UIAxes_2)
            cla(app.PolarPlot)

            selectedColor = uisetcolor; 
            
            if length(selectedColor) == 3
                app.Mainapp.PlotAppearance.PhaseSyncPlots.AngleDiffMeanColor = selectedColor;   
            end

            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
        end

        % Menu selected function: LineWidthPhaseAngleDifferencesMenu
        function LineWidthPhaseAngleDifferencesMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes_2,'Linewidth',app.Mainapp,"MainDataphasesyncDiffsLineWidthPhases");
            waitfor(h);
            
            cla(app.UIAxes_2)
            cla(app.PolarPlot)

            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
        end

        % Menu selected function: LineWidthAveragePhaseVectorMenu
        function LineWidthAveragePhaseVectorMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes_2,'Linewidth',app.Mainapp,"MainDataphasesyncDiffsLineWidthAverage");
            waitfor(h);
            
            cla(app.UIAxes_2)
            cla(app.PolarPlot)

            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
        end

        % Menu selected function: RESETMenu_2
        function RESETMenu_2Selected(app, event)
            [app.Mainapp.PlotAppearance] = Organize_Set_Standard_PlotAppearance("PhaseSyncPlotPhasDiffs",app.Mainapp.PlotAppearance);
            
            cla(app.UIAxes_2)
            cla(app.PolarPlot)

            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
        end

        % Menu selected function: SaveCostumeAppearanceMenu
        function SaveCostumeAppearanceMenuSelected(app, event)
            app.Mainapp.PlotAppearance = Utility_Save_Load_Delete_Plot_Appearance(app.Mainapp.PlotAppearance,app.Mainapp.executableFolder,"Save");
        end

        % Menu selected function: DeleteCostumeAppearanceMenu
        function DeleteCostumeAppearanceMenuSelected(app, event)
            app.Mainapp.PlotAppearance = Utility_Save_Load_Delete_Plot_Appearance(app.Mainapp.PlotAppearance,app.Mainapp.executableFolder,"Delete");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PhaseSynchronizationWindowUIFigure = uifigure('Visible', 'off');
            app.PhaseSynchronizationWindowUIFigure.Name = 'Phase Synchronization Window';
            app.PhaseSynchronizationWindowUIFigure.Icon = 'Logo.png';
            app.PhaseSynchronizationWindowUIFigure.CloseRequestFcn = createCallbackFcn(app, @PhaseSynchronizationWindowUIFigureCloseRequest, true);
            app.SnapshotMenu = uimenu(app.PhaseSynchronizationWindowUIFigure);
            app.SnapshotMenu.Text = '   Snapshot   ';
            app.SaveSnapshotofWindowMenu = uimenu(app.SnapshotMenu);
            app.SaveSnapshotofWindowMenu.Text = 'Save Snapshot of Window';
            app.PNGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.PNGMenu.MenuSelectedFcn = createCallbackFcn(app, @PNGMenuSelected, true);
            app.PNGMenu.Text = 'PNG';
            app.JPGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.JPGMenu.MenuSelectedFcn = createCallbackFcn(app, @JPGMenuSelected, true);
            app.JPGMenu.Text = 'JPG';
            app.PlotMenu = uimenu(app.PhaseSynchronizationWindowUIFigure);
            app.PlotMenu.Text = '   Plot   ';
            app.ChangePlotPropertiesMenu = uimenu(app.PlotMenu);
            app.ChangePlotPropertiesMenu.Text = 'Change Plot Properties';
            app.AlltoAllSyncPlotMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.AlltoAllSyncPlotMenu.Text = 'All-to-All Sync Plot';
            app.FontSizeMenu = uimenu(app.AlltoAllSyncPlotMenu);
            app.FontSizeMenu.MenuSelectedFcn = createCallbackFcn(app, @FontSizeMenuSelected, true);
            app.FontSizeMenu.Text = 'FontSize';
            app.XLabelMenu = uimenu(app.AlltoAllSyncPlotMenu);
            app.XLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @XLabelMenuSelected, true);
            app.XLabelMenu.Text = 'XLabel';
            app.YLabelMenu = uimenu(app.AlltoAllSyncPlotMenu);
            app.YLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @YLabelMenuSelected, true);
            app.YLabelMenu.Text = 'YLabel';
            app.CLabelMenu = uimenu(app.AlltoAllSyncPlotMenu);
            app.CLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @CLabelMenuSelected, true);
            app.CLabelMenu.Text = 'CLabel';
            app.TitleMenu = uimenu(app.AlltoAllSyncPlotMenu);
            app.TitleMenu.MenuSelectedFcn = createCallbackFcn(app, @TitleMenuSelected, true);
            app.TitleMenu.Text = 'Title';
            app.RESETMenu = uimenu(app.AlltoAllSyncPlotMenu);
            app.RESETMenu.MenuSelectedFcn = createCallbackFcn(app, @RESETMenuSelected, true);
            app.RESETMenu.Text = 'RESET';
            app.PhaseAngleDifferencePlotMenu_2 = uimenu(app.ChangePlotPropertiesMenu);
            app.PhaseAngleDifferencePlotMenu_2.Text = 'Phase Angle Difference Plot';
            app.FontSizeMenu_2 = uimenu(app.PhaseAngleDifferencePlotMenu_2);
            app.FontSizeMenu_2.MenuSelectedFcn = createCallbackFcn(app, @FontSizeMenu_2Selected, true);
            app.FontSizeMenu_2.Text = 'FontSize';
            app.ColorPhaseAngleDifferencesMenu = uimenu(app.PhaseAngleDifferencePlotMenu_2);
            app.ColorPhaseAngleDifferencesMenu.MenuSelectedFcn = createCallbackFcn(app, @ColorPhaseAngleDifferencesMenuSelected, true);
            app.ColorPhaseAngleDifferencesMenu.Text = 'Color Phase Angle Differences';
            app.ColorAveragePhaseVectorMenu = uimenu(app.PhaseAngleDifferencePlotMenu_2);
            app.ColorAveragePhaseVectorMenu.MenuSelectedFcn = createCallbackFcn(app, @ColorAveragePhaseVectorMenuSelected, true);
            app.ColorAveragePhaseVectorMenu.Text = 'Color Average Phase Vector ';
            app.LineWidthPhaseAngleDifferencesMenu = uimenu(app.PhaseAngleDifferencePlotMenu_2);
            app.LineWidthPhaseAngleDifferencesMenu.MenuSelectedFcn = createCallbackFcn(app, @LineWidthPhaseAngleDifferencesMenuSelected, true);
            app.LineWidthPhaseAngleDifferencesMenu.Text = 'LineWidth Phase Angle Differences';
            app.LineWidthAveragePhaseVectorMenu = uimenu(app.PhaseAngleDifferencePlotMenu_2);
            app.LineWidthAveragePhaseVectorMenu.MenuSelectedFcn = createCallbackFcn(app, @LineWidthAveragePhaseVectorMenuSelected, true);
            app.LineWidthAveragePhaseVectorMenu.Text = 'LineWidth Average Phase Vector ';
            app.BackgorundColorMenu_2 = uimenu(app.PhaseAngleDifferencePlotMenu_2);
            app.BackgorundColorMenu_2.MenuSelectedFcn = createCallbackFcn(app, @BackgorundColorMenu_2Selected, true);
            app.BackgorundColorMenu_2.Text = 'Backgorund Color';
            app.TitleMenu_2 = uimenu(app.PhaseAngleDifferencePlotMenu_2);
            app.TitleMenu_2.MenuSelectedFcn = createCallbackFcn(app, @TitleMenu_2Selected, true);
            app.TitleMenu_2.Text = 'Title';
            app.RESETMenu_2 = uimenu(app.PhaseAngleDifferencePlotMenu_2);
            app.RESETMenu_2.MenuSelectedFcn = createCallbackFcn(app, @RESETMenu_2Selected, true);
            app.RESETMenu_2.Text = 'RESET';
            app.SaveCostumeAppearanceMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.SaveCostumeAppearanceMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveCostumeAppearanceMenuSelected, true);
            app.SaveCostumeAppearanceMenu.Text = 'Save Costume Appearance';
            app.DeleteCostumeAppearanceMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.DeleteCostumeAppearanceMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteCostumeAppearanceMenuSelected, true);
            app.DeleteCostumeAppearanceMenu.Text = 'Delete Costume Appearance';
            app.ExportDataMenu = uimenu(app.PhaseSynchronizationWindowUIFigure);
            app.ExportDataMenu.Text = '   Export Data   ';
            app.matMenu = uimenu(app.ExportDataMenu);
            app.matMenu.MenuSelectedFcn = createCallbackFcn(app, @matMenuSelected, true);
            app.matMenu.Text = '.mat';
            app.txtMenu = uimenu(app.ExportDataMenu);
            app.txtMenu.Text = '.txt';
            app.csvMenu = uimenu(app.ExportDataMenu);
            app.csvMenu.Text = '.csv';
            app.UIAxes_2 = uiaxes(app.PhaseSynchronizationWindowUIFigure);
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.Panel = uipanel(app.PhaseSynchronizationWindowUIFigure);
            app.ChannelSelectionPanel = uipanel(app.PhaseSynchronizationWindowUIFigure);
            app.ChannelSelectionPanel.Title = 'Channel Selection';
            app.Channel1DropDownLabel = uilabel(app.ChannelSelectionPanel);
            app.Channel1DropDownLabel.Text = 'Channel 1';
            app.Channel1DropDown = uidropdown(app.ChannelSelectionPanel);
            app.Channel1DropDown.ValueChangedFcn = createCallbackFcn(app, @Channel1DropDownValueChanged, true);
            app.Channel2DropDownLabel = uilabel(app.ChannelSelectionPanel);
            app.Channel2DropDownLabel.Text = 'Channel 2';
            app.Channel2DropDown = uidropdown(app.ChannelSelectionPanel);
            app.Channel2DropDown.ValueChangedFcn = createCallbackFcn(app, @Channel2DropDownValueChanged, true);
            app.FilterParameterPanel = uipanel(app.PhaseSynchronizationWindowUIFigure);
            app.FilterParameterPanel.Title = 'Filter Parameter';
            app.NarrLabel = uilabel(app.FilterParameterPanel);
            app.NarrLabel.Text = {'Narrowband Cutoff'; '[Lower,Higher]'};
            app.MinMaxFrequencylowerhigherinHzEditField = uieditfield(app.FilterParameterPanel, 'text');
            app.MinMaxFrequencylowerhigherinHzEditField.ValueChangedFcn = createCallbackFcn(app, @MinMaxFrequencylowerhigherinHzEditFieldValueChanged, true);
            app.MinMaxFrequencylowerhigherinHzEditField.Value = '10,15';
            app.NarrowbandFilterorderEditFieldLabel = uilabel(app.FilterParameterPanel);
            app.NarrowbandFilterorderEditFieldLabel.Text = {'Narrowband'; 'Filterorder'};
            app.NarrowbandFilterorderEditField = uieditfield(app.FilterParameterPanel, 'text');
            app.NarrowbandFilterorderEditField.ValueChangedFcn = createCallbackFcn(app, @NarrowbandFilterorderEditFieldValueChanged, true);
            app.FilterParameterLabel = uilabel(app.PhaseSynchronizationWindowUIFigure);
            app.FilterParameterLabel.Text = 'Filter Parameter';
            app.ChannelSelectionLabel = uilabel(app.PhaseSynchronizationWindowUIFigure);
            app.ChannelSelectionLabel.Text = 'Channel Selection';
            app.CloseButton = uibutton(app.PhaseSynchronizationWindowUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.InformationTextAreaLabel = uilabel(app.PhaseSynchronizationWindowUIFigure);
            app.InformationTextAreaLabel.Text = 'Information';
            app.InformationTextArea = uitextarea(app.PhaseSynchronizationWindowUIFigure);
            % Show the figure after all components are created
            app.PhaseSynchronizationWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main_Plot_Phase_Synchro(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.PhaseSynchronizationWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.PhaseSynchronizationWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PhaseSynchronizationWindowUIFigure)
        end
    end
end