classdef Spectral_Power_Estimate_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PowerEstimateWindowUIFigure     matlab.ui.Figure
        SnapshotMenu                    matlab.ui.container.Menu
        SaveSnapshotofWindowMenu        matlab.ui.container.Menu
        PNGMenu                         matlab.ui.container.Menu
        JPGMenu                         matlab.ui.container.Menu
        PlotMenu                        matlab.ui.container.Menu
        ChangePlotPropertiesMenu        matlab.ui.container.Menu
        PowerEstimatePlotMenu           matlab.ui.container.Menu
        FontSizeMenu                    matlab.ui.container.Menu
        BarColorMenu                    matlab.ui.container.Menu
        BackgorundColorMenu             matlab.ui.container.Menu
        XLabelMenu                      matlab.ui.container.Menu
        YLabelMenu                      matlab.ui.container.Menu
        RESETMenu                       matlab.ui.container.Menu
        SaveCostumeAppearanceMenu       matlab.ui.container.Menu
        DeleteCostumeAppearanceMenu     matlab.ui.container.Menu
        ExportDataMenu                  matlab.ui.container.Menu
        matMenu                         matlab.ui.container.Menu
        txtMenu                         matlab.ui.container.Menu
        csvMenu                         matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        RightPanel                      matlab.ui.container.Panel
        NeuromodLabel                   matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        PowerEstimateWindowLabel        matlab.ui.control.Label
        CloseButton                     matlab.ui.control.Button
        PowerEstimateOptionsPanel       matlab.ui.container.Panel
        DataTypeDropDown                matlab.ui.control.DropDown
        DataTypeDropDownLabel           matlab.ui.control.Label
        LockYLimCheckBox                matlab.ui.control.CheckBox
        ChannelSelectionPreprocessedChannelEditField  matlab.ui.control.EditField
        ChannelSelectionEditFieldLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Mainapp = []; % Description
        ExistflagSDE = [];
        PDLim = [];
        Startup = 1;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            movegui(app.PowerEstimateWindowUIFigure, 'north');
            
            app.Mainapp = Mainapp;
            app.Mainapp.SpectralEstApp = app;

            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.LivePowerEstimateWindow.FontSize;
            app.UIAxes.Color = app.Mainapp.PlotAppearance.LivePowerEstimateWindow.BackgroundColor;

            if isfield(app.Mainapp.Data,'Preprocessed')
                app.DataTypeDropDown.Items{1} = 'Preprocessed';
                app.DataTypeDropDown.Items{2} = 'Raw';
            else
                app.DataTypeDropDown.Items{1} = 'Raw';
            end

            app.ExistflagSDE = 1;

           % app.ChannelSelectionPreprocessedChannelEditField.Value = strcat(num2str(1),",",num2str(size(app.Mainapp.Data.Raw,1)));

            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
           
            CommaIndex = strfind(app.ChannelSelectionPreprocessedChannelEditField.Value,",");
            ChannelSelection(1) = str2double(app.ChannelSelectionPreprocessedChannelEditField.Value(1:CommaIndex-1));
            ChannelSelection(2) = str2double(app.ChannelSelectionPreprocessedChannelEditField.Value(CommaIndex+1:end));

            xtickangle(app.UIAxes, 45);
            
            app.Mainapp.SpectralEstApp = app;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
                      
            app.Startup = 0;
        end

        % Close request function: PowerEstimateWindowUIFigure
        function PowerEstimateWindowUIFigureCloseRequest(app, event)
            app.Mainapp.SpectralEstApp = [];
            
            if ~isempty(app.Mainapp.ProbeViewWindowHandle) % delete option from probe view 
                for i = 1:length(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                    if strcmp(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Main Plot Power Estimate')
                        app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items(i) = [];
                        break;
                    end
                end
            else % if no probe view available
 
            end

            app.ExistflagSDE = [];
            delete(app)
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            if ~isempty(app.Mainapp.ProbeViewWindowHandle) % delete option from probe view 
                for i = 1:length(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                    if strcmp(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Main Plot Power Estimate')
                        app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items(i) = [];
                        break;
                    end
                end
            else % if no probe view available
 
            end

            app.ExistflagSDE = [];
            delete(app);
        end

        % Value changed function: 
        % ChannelSelectionPreprocessedChannelEditField
        function ChannelSelectionPreprocessedChannelEditFieldValueChanged(app, event)
            
            if ~isempty(app.ChannelSelectionPreprocessedChannelEditField.Value)
                [app.ChannelSelectionPreprocessedChannelEditField.Value] = Utility_SimpleCheckInputs(app.ChannelSelectionPreprocessedChannelEditField.Value,"Two",strcat('1,',num2str(size(app.Mainapp.Data.Raw,1))),1,0);
            end

            if ~isempty(app.ChannelSelectionPreprocessedChannelEditField.Value)
                app.Mainapp.ChannelChange = "EditField";
    
                %% Show mV scale if only one channel shown
                commaindicie = find(app.ChannelSelectionPreprocessedChannelEditField.Value==',');
                SelectedChannel(1) = str2double(app.ChannelSelectionPreprocessedChannelEditField.Value(1:commaindicie(1)-1));
                SelectedChannel(2) = str2double(app.ChannelSelectionPreprocessedChannelEditField.Value(commaindicie(1)+1:end));
    
                app.Mainapp.ActiveChannel = app.Mainapp.Data.Info.ProbeInfo.ActiveChannel(SelectedChannel(1):SelectedChannel(2));
    
                app.Mainapp.SpectralEstApp = app;
                Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            end
        end

        % Value changed function: LockYLimCheckBox
        function LockYLimCheckBoxValueChanged(app, event)
            value = app.LockYLimCheckBox.Value;
            
        end

        % Menu selected function: PNGMenu
        function PNGMenuSelected(app, event)
            [file, path] = uiputfile('*.png', 'Save as');

            exportapp(app.PowerEstimateWindowUIFigure,fullfile(path,file)) 
        end

        % Menu selected function: JPGMenu
        function JPGMenuSelected(app, event)
            [file, path] = uiputfile('*.jpg', 'Save as');

            exportapp(app.PowerEstimateWindowUIFigure,fullfile(path,file)) 
        end

        % Menu selected function: matMenu
        function matMenuSelected(app, event)
            

            TimeRangePlot = str2double(app.Mainapp.TimeRangeViewBox.Value(1:end-1));
            if strcmp(app.Mainapp.DropDown.Value,"Preprocessed Data")
                if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                else
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                end
            else
                StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
            end

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Live_Spectral_Power_Estimate_Main_Window");
        end

        % Menu selected function: txtMenu
        function txtMenuSelected(app, event)
            TimeRangePlot = str2double(app.Mainapp.TimeRangeViewBox.Value(1:end-1));
            if strcmp(app.Mainapp.DropDown.Value,"Preprocessed Data")
                if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                else
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                end
            else
                StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
            end
            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Live_Spectral_Power_Estimate_Main_Window");
        end

        % Menu selected function: csvMenu
        function csvMenuSelected(app, event)
            TimeRangePlot = str2double(app.Mainapp.TimeRangeViewBox.Value(1:end-1));
            if strcmp(app.Mainapp.DropDown.Value,"Preprocessed Data")
                if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                else
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                end
            else
                StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
            end
            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".csv",app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Live_Spectral_Power_Estimate_Main_Window");
        end

        % Menu selected function: FontSizeMenu
        function FontSizeMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'fontsize',app.Mainapp,"LivePEWindowDataFontSize");
            waitfor(h);

            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.LivePowerEstimateWindow.FontSize;
        end

        % Menu selected function: BarColorMenu
        function BarColorMenuSelected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.Mainapp.PlotAppearance.LivePowerEstimateWindow.BarColor = selectedColor;
            end

            app.Mainapp.SpectralEstApp = app;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
        end

        % Menu selected function: BackgorundColorMenu
        function BackgorundColorMenuSelected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.Mainapp.PlotAppearance.LivePowerEstimateWindow.BackgroundColor = selectedColor;
                app.UIAxes.Color = app.Mainapp.PlotAppearance.LivePowerEstimateWindow.BackgroundColor;
            end
        end

        % Menu selected function: XLabelMenu
        function XLabelMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'xlabel',app.Mainapp,"LivePEWindowXLabel");
            waitfor(h);

            app.Mainapp.SpectralEstApp = app;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
        end

        % Menu selected function: YLabelMenu
        function YLabelMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'ylabel',app.Mainapp,"LivePEWindowYLabel");
            waitfor(h);

            app.Mainapp.SpectralEstApp = app;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
        end

        % Menu selected function: RESETMenu
        function RESETMenuSelected(app, event)
            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";

            [app.Mainapp.PlotAppearance] = Organize_Set_Standard_PlotAppearance("PowerEstimatePlot",app.Mainapp.PlotAppearance);

            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.LivePowerEstimateWindow.FontSize;
            app.UIAxes.Color = app.Mainapp.PlotAppearance.LivePowerEstimateWindow.BackgroundColor;

            app.Mainapp.SpectralEstApp = app;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
        end

        % Value changed function: DataTypeDropDown
        function DataTypeDropDownValueChanged(app, event)
            
            app.Mainapp.SpectralEstApp = app;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
        end

        % Menu selected function: SaveCostumeAppearanceMenu
        function SaveCostumeAppearanceMenuSelected(app, event)
            app.Mainapp.PlotAppearance = Utility_Save_Load_Delete_Plot_Appearance(app.Mainapp.PlotAppearance,app.Mainapp.executableFolder,"Save");
        end

        % Menu selected function: DeleteCostumeAppearanceMenu
        function DeleteCostumeAppearanceMenuSelected(app, event)
            app.Mainapp.PlotAppearance = Utility_Save_Load_Delete_Plot_Appearance(app.Mainapp.PlotAppearance,app.Mainapp.executableFolder,"Delete");
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.PowerEstimateWindowUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {432, 432};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PowerEstimateWindowUIFigure = uifigure('Visible', 'off');
            app.PowerEstimateWindowUIFigure.Name = 'Power Estimate Window';
            app.PowerEstimateWindowUIFigure.Icon = 'Logo.png';
            app.PowerEstimateWindowUIFigure.CloseRequestFcn = createCallbackFcn(app, @PowerEstimateWindowUIFigureCloseRequest, true);
            app.PowerEstimateWindowUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.SnapshotMenu = uimenu(app.PowerEstimateWindowUIFigure);
            app.SnapshotMenu.Text = '   Snapshot   ';
            app.SaveSnapshotofWindowMenu = uimenu(app.SnapshotMenu);
            app.SaveSnapshotofWindowMenu.Text = 'Save Snapshot of Window';
            app.PNGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.PNGMenu.MenuSelectedFcn = createCallbackFcn(app, @PNGMenuSelected, true);
            app.PNGMenu.Text = 'PNG';
            app.JPGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.JPGMenu.MenuSelectedFcn = createCallbackFcn(app, @JPGMenuSelected, true);
            app.JPGMenu.Text = 'JPG';
            app.PlotMenu = uimenu(app.PowerEstimateWindowUIFigure);
            app.PlotMenu.Text = '   Plot   ';
            app.ChangePlotPropertiesMenu = uimenu(app.PlotMenu);
            app.ChangePlotPropertiesMenu.Text = 'Change Plot Properties';
            app.PowerEstimatePlotMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.PowerEstimatePlotMenu.Text = 'Power Estimate Plot';
            app.FontSizeMenu = uimenu(app.PowerEstimatePlotMenu);
            app.FontSizeMenu.MenuSelectedFcn = createCallbackFcn(app, @FontSizeMenuSelected, true);
            app.FontSizeMenu.Text = 'FontSize';
            app.BarColorMenu = uimenu(app.PowerEstimatePlotMenu);
            app.BarColorMenu.MenuSelectedFcn = createCallbackFcn(app, @BarColorMenuSelected, true);
            app.BarColorMenu.Text = 'Bar Color';
            app.BackgorundColorMenu = uimenu(app.PowerEstimatePlotMenu);
            app.BackgorundColorMenu.MenuSelectedFcn = createCallbackFcn(app, @BackgorundColorMenuSelected, true);
            app.BackgorundColorMenu.Text = 'Backgorund Color';
            app.XLabelMenu = uimenu(app.PowerEstimatePlotMenu);
            app.XLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @XLabelMenuSelected, true);
            app.XLabelMenu.Text = 'XLabel';
            app.YLabelMenu = uimenu(app.PowerEstimatePlotMenu);
            app.YLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @YLabelMenuSelected, true);
            app.YLabelMenu.Text = 'YLabel';
            app.RESETMenu = uimenu(app.PowerEstimatePlotMenu);
            app.RESETMenu.MenuSelectedFcn = createCallbackFcn(app, @RESETMenuSelected, true);
            app.RESETMenu.Text = 'RESET';
            app.SaveCostumeAppearanceMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.SaveCostumeAppearanceMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveCostumeAppearanceMenuSelected, true);
            app.SaveCostumeAppearanceMenu.Text = 'Save Costume Appearance';
            app.DeleteCostumeAppearanceMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.DeleteCostumeAppearanceMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteCostumeAppearanceMenuSelected, true);
            app.DeleteCostumeAppearanceMenu.Text = 'Delete Costume Appearance';
            app.ExportDataMenu = uimenu(app.PowerEstimateWindowUIFigure);
            app.ExportDataMenu.Text = '   Export Data   ';
            app.matMenu = uimenu(app.ExportDataMenu);
            app.matMenu.MenuSelectedFcn = createCallbackFcn(app, @matMenuSelected, true);
            app.matMenu.Text = '.mat';
            app.txtMenu = uimenu(app.ExportDataMenu);
            app.txtMenu.MenuSelectedFcn = createCallbackFcn(app, @txtMenuSelected, true);
            app.txtMenu.Text = '.txt';
            app.csvMenu = uimenu(app.ExportDataMenu);
            app.csvMenu.MenuSelectedFcn = createCallbackFcn(app, @csvMenuSelected, true);
            app.csvMenu.Text = '.csv';
            app.GridLayout = uigridlayout(app.PowerEstimateWindowUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            app.PowerEstimateOptionsPanel = uipanel(app.RightPanel);
            app.PowerEstimateOptionsPanel.Title = 'Power Estimate Options';
            app.ChannelSelectionEditFieldLabel = uilabel(app.PowerEstimateOptionsPanel);
            app.ChannelSelectionEditFieldLabel.Enable = 'off';
            app.ChannelSelectionEditFieldLabel.Visible = 'off';
            app.ChannelSelectionEditFieldLabel.Text = {'Channel Selection'; ''};
            app.ChannelSelectionPreprocessedChannelEditField = uieditfield(app.PowerEstimateOptionsPanel, 'text');
            app.ChannelSelectionPreprocessedChannelEditField.ValueChangedFcn = createCallbackFcn(app, @ChannelSelectionPreprocessedChannelEditFieldValueChanged, true);
            app.ChannelSelectionPreprocessedChannelEditField.Editable = 'off';
            app.ChannelSelectionPreprocessedChannelEditField.Enable = 'off';
            app.ChannelSelectionPreprocessedChannelEditField.Visible = 'off';
            app.LockYLimCheckBox = uicheckbox(app.PowerEstimateOptionsPanel);
            app.LockYLimCheckBox.ValueChangedFcn = createCallbackFcn(app, @LockYLimCheckBoxValueChanged, true);
            app.LockYLimCheckBox.Text = '   Lock YLim';
            app.LockYLimCheckBox.Value = true;
            app.DataTypeDropDownLabel = uilabel(app.PowerEstimateOptionsPanel);
            app.DataTypeDropDownLabel.Text = 'Data Type';
            app.DataTypeDropDown = uidropdown(app.PowerEstimateOptionsPanel);
            app.DataTypeDropDown.Items = {};
            app.DataTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DataTypeDropDownValueChanged, true);
            app.DataTypeDropDown.Value = {};
            app.CloseButton = uibutton(app.RightPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.PowerEstimateWindowLabel = uilabel(app.RightPanel);
            app.PowerEstimateWindowLabel.Text = 'Power Estimate Window';
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodLabel = uilabel(app.RightPanel);
            app.NeuromodLabel.Text = 'Neuromod';
            % Show the figure after all components are created
            app.PowerEstimateWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Spectral_Power_Estimate_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.PowerEstimateWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.PowerEstimateWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PowerEstimateWindowUIFigure)
        end
    end
end