classdef Artefact_Rejection_Continous_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ContinousArtefactRejectionUIFigure  matlab.ui.Figure
        SpacingSlider                   matlab.ui.control.Slider
        SpacingSliderLabel              matlab.ui.control.Label
        EventstoPlotDropDown            matlab.ui.control.DropDown
        EventstoPlotDropDownLabel       matlab.ui.control.Label
        AddArtefactRejectiontoPipelineButton  matlab.ui.control.Button
        InformationTextArea             matlab.ui.control.TextArea
        InformationTextAreaLabel        matlab.ui.control.Label
        CloseButton                     matlab.ui.control.Button
        NeuromodToolboxLabel            matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        StimulationArtefactRejectionLabel  matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        RejectionMethodDropDown         matlab.ui.control.DropDown
        RejectionMethodDropDownLabel    matlab.ui.control.Label
        TimeAroundEventsEditField       matlab.ui.control.EditField
        TimeAroundEventsEditFieldLabel  matlab.ui.control.Label
        EventChannelforStimulationDropDown  matlab.ui.control.DropDown
        EventChannelofStimulationDropDownLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        PreproApp = [] % Description
        PlotTimeVector = [];
        StimArtefactInfo = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, PreproApp)
            %% Initiate GUI 
            movegui(app.ContinousArtefactRejectionUIFigure, 'center');
            app.PreproApp = PreproApp;

            app.SpacingSlider.Limits = app.PreproApp.Mainapp.Slider.Limits;
            app.SpacingSlider.Value = app.PreproApp.Mainapp.Slider.Value;
            
            for neventchannel = 1:length(app.PreproApp.Mainapp.Data.Events)
                app.EventChannelforStimulationDropDown.Items{neventchannel} = app.PreproApp.Mainapp.Data.Info.EventChannelNames{neventchannel};
            end

            app.EventstoPlotDropDown.Items{1} = 'Mean over all Events';

            for i = 1:length(app.PreproApp.Mainapp.Data.Events{1})
                app.EventstoPlotDropDown.Items{i+1} = num2str(i);
            end

            %% Show Info in text area

            SelectedEventIndicie = [];
            for neventchannel = 1:length(app.EventChannelforStimulationDropDown.Items)
                if strcmp(app.EventChannelforStimulationDropDown.Value,app.PreproApp.Mainapp.Data.Info.EventChannelNames{neventchannel})
                    SelectedEventIndicie = neventchannel;
                end
            end

            Texttoshow = ["Select the event channel containing time points of your stimulation and specify the desired time range around artefacts. Values in that range get interpolated. 'Events to Plot' dropdown menu is only for plotting purposes! Interpolation is applied to all events and channel."];
            Texttoshow = [Texttoshow;"";"Selected event channel for getting stimuls artefact time points: ";"";app.EventChannelforStimulationDropDown.Value];
            Texttoshow = [Texttoshow;strcat("Number of triggers in that channel: ",num2str(length(app.PreproApp.Mainapp.Data.Events{SelectedEventIndicie})))];

            app.InformationTextArea.Value = Texttoshow;

            %% Extract and plot stim artefacts

            [~,app.StimArtefactInfo] = Preprocess_Extract_and_Plot_Stimulation_Artefact(app.PreproApp.Mainapp.Data, app.TimeAroundEventsEditField.Value, app.EventChannelforStimulationDropDown.Value, app.EventstoPlotDropDown.Value, app.SpacingSlider.Value, app.UIAxes);            
            
        end

        % Callback function
        function ArtefactRejectionusingEventsButtonPushed(app, event)


        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: SpacingSlider
        function SpacingSliderValueChanged(app, event)
            value = app.SpacingSlider.Value;
            %% Extract and plot stim artefacts

            [~,app.StimArtefactInfo] = Preprocess_Extract_and_Plot_Stimulation_Artefact(app.PreproApp.Mainapp.Data, app.TimeAroundEventsEditField.Value, app.EventChannelforStimulationDropDown.Value, app.EventstoPlotDropDown.Value, app.SpacingSlider.Value, app.UIAxes);            
            
        end

        % Value changed function: EventstoPlotDropDown
        function EventstoPlotDropDownValueChanged(app, event)
            value = app.EventstoPlotDropDown.Value;
            %% Extract and plot stim artefacts

            [~,app.StimArtefactInfo] = Preprocess_Extract_and_Plot_Stimulation_Artefact(app.PreproApp.Mainapp.Data, app.TimeAroundEventsEditField.Value, app.EventChannelforStimulationDropDown.Value, app.EventstoPlotDropDown.Value, app.SpacingSlider.Value, app.UIAxes);            
            
        end

        % Value changed function: TimeAroundEventsEditField
        function TimeAroundEventsEditFieldValueChanged(app, event)
            value = app.TimeAroundEventsEditField.Value;
            %% Extract and plot stim artefacts

            [~,app.StimArtefactInfo] = Preprocess_Extract_and_Plot_Stimulation_Artefact(app.PreproApp.Mainapp.Data, app.TimeAroundEventsEditField.Value, app.EventChannelforStimulationDropDown.Value, app.EventstoPlotDropDown.Value, app.SpacingSlider.Value, app.UIAxes);            
            
        end

        % Value changed function: EventChannelforStimulationDropDown
        function EventChannelforStimulationDropDownValueChanged(app, event)
            value = app.EventChannelforStimulationDropDown.Value;
            %% Extract and plot stim artefacts

            [~,app.StimArtefactInfo] = Preprocess_Extract_and_Plot_Stimulation_Artefact(app.PreproApp.Mainapp.Data, app.TimeAroundEventsEditField.Value, app.EventChannelforStimulationDropDown.Value, app.EventstoPlotDropDown.Value, app.SpacingSlider.Value, app.UIAxes);            
            
            %% Show Info in text area

            SelectedEventIndicie = [];
            for neventchannel = 1:length(app.EventChannelforStimulationDropDown.Items)
                if strcmp(app.EventChannelforStimulationDropDown.Value,app.PreproApp.Mainapp.Data.Info.EventChannelNames{neventchannel})
                    SelectedEventIndicie = neventchannel;
                end
            end

            Texttoshow = ["Select the event channel containing time points of your stimulation and specify the desired time range around artefacts. Values in that range get interpolated. 'Events to Plot' dropdown menu is only for plotting purposes! Interpolation is applied to all events and channel."];
            Texttoshow = [Texttoshow;"";"Selected event channel for getting stimuls artefact time points: ";"";app.EventChannelforStimulationDropDown.Value];
            Texttoshow = [Texttoshow;strcat("Number of triggers in that channel: ",num2str(length(app.PreproApp.Mainapp.Data.Events{SelectedEventIndicie})))];

            app.InformationTextArea.Value = Texttoshow;
        end

        % Button pushed function: AddArtefactRejectiontoPipelineButton
        function AddArtefactRejectiontoPipelineButtonPushed(app, event)
            % Add Selection to the pipeline. Filterorder, cutoff and all
            %% other relevant parameters are extracted and saved here (bc multiple filter can be selected and settings have to be saved in between)!
            % Additionally checks logic if user wants to add two or more of the following
            % three filters: Low Pass, High Pass, Narrwoband. This is
            % rejected since it makes no sense. Instead, the existing added
            % filter type is replace by the most recent one added.
            [app.PreproApp.Info,app.PreproApp.PreprocessingSteps,InfoText] = Preprocess_Module_Construct_Pipeline("StimArtefactRejection",app.PreproApp.Info,app.PreproApp.PreprocessingSteps,0,app.PreproApp.FilterMethodDropDown.Value,app.PreproApp.FilterTypeDropDown.Value,app.PreproApp.CuttoffFrequencyHzEditField.Value,app.PreproApp.FilterDirectionDropDown.Value,app.PreproApp.FilterOrderEditField.Value,app.PreproApp.DownsampleFactorEditField.Value,app.PreproApp.Mainapp.Data.Info.NativeSamplingRate,app.StimArtefactInfo);
            if ~isempty(InfoText)
                app.PreproApp.TextArea.Value = InfoText;
            end

            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ContinousArtefactRejectionUIFigure = uifigure('Visible', 'off');
            app.ContinousArtefactRejectionUIFigure.Name = 'Continous Artefact Rejection';
            app.ContinousArtefactRejectionUIFigure.Icon = 'Logo.png';
            app.UIAxes = uiaxes(app.ContinousArtefactRejectionUIFigure);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.YTickLabel = '';
            app.Panel = uipanel(app.ContinousArtefactRejectionUIFigure);
            app.EventChannelofStimulationDropDownLabel = uilabel(app.Panel);
            app.EventChannelofStimulationDropDownLabel.Text = {'Event Channel'; 'of Stimulation'};
            app.EventChannelforStimulationDropDown = uidropdown(app.Panel);
            app.EventChannelforStimulationDropDown.Items = {''};
            app.EventChannelforStimulationDropDown.ValueChangedFcn = createCallbackFcn(app, @EventChannelforStimulationDropDownValueChanged, true);
            app.EventChannelforStimulationDropDown.Value = '';
            app.TimeAroundEventsEditFieldLabel = uilabel(app.Panel);
            app.TimeAroundEventsEditFieldLabel.Text = 'Time Around Events';
            app.TimeAroundEventsEditField = uieditfield(app.Panel, 'text');
            app.TimeAroundEventsEditField.ValueChangedFcn = createCallbackFcn(app, @TimeAroundEventsEditFieldValueChanged, true);
            app.TimeAroundEventsEditField.Value = '-0.005,0.005';
            app.RejectionMethodDropDownLabel = uilabel(app.Panel);
            app.RejectionMethodDropDownLabel.Text = 'Rejection Method';
            app.RejectionMethodDropDown = uidropdown(app.Panel);
            app.RejectionMethodDropDown.Items = {'Interpolate'};
            app.RejectionMethodDropDown.Value = 'Interpolate';
            app.StimulationArtefactRejectionLabel = uilabel(app.ContinousArtefactRejectionUIFigure);
            app.StimulationArtefactRejectionLabel.Text = 'Stimulation Artefact Rejection';
            app.Image = uiimage(app.ContinousArtefactRejectionUIFigure);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodToolboxLabel = uilabel(app.ContinousArtefactRejectionUIFigure);
            app.NeuromodToolboxLabel.Text = 'Neuromod Toolbox';
            app.CloseButton = uibutton(app.ContinousArtefactRejectionUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.InformationTextAreaLabel = uilabel(app.ContinousArtefactRejectionUIFigure);
            app.InformationTextAreaLabel.Text = 'Information';
            app.InformationTextArea = uitextarea(app.ContinousArtefactRejectionUIFigure);
            app.AddArtefactRejectiontoPipelineButton = uibutton(app.ContinousArtefactRejectionUIFigure, 'push');
            app.AddArtefactRejectiontoPipelineButton.ButtonPushedFcn = createCallbackFcn(app, @AddArtefactRejectiontoPipelineButtonPushed, true);
            app.AddArtefactRejectiontoPipelineButton.Text = {'Add Artefact Rejection'; 'to Pipeline'};
            app.EventstoPlotDropDownLabel = uilabel(app.ContinousArtefactRejectionUIFigure);
            app.EventstoPlotDropDownLabel.Text = 'Events to Plot';
            app.EventstoPlotDropDown = uidropdown(app.ContinousArtefactRejectionUIFigure);
            app.EventstoPlotDropDown.Items = {'Mean over all Events'};
            app.EventstoPlotDropDown.ValueChangedFcn = createCallbackFcn(app, @EventstoPlotDropDownValueChanged, true);
            app.EventstoPlotDropDown.Value = 'Mean over all Events';
            app.SpacingSliderLabel = uilabel(app.ContinousArtefactRejectionUIFigure);
            app.SpacingSliderLabel.Text = 'Spacing';
            app.SpacingSlider = uislider(app.ContinousArtefactRejectionUIFigure);
            app.SpacingSlider.ValueChangedFcn = createCallbackFcn(app, @SpacingSliderValueChanged, true);
            % Show the figure after all components are created
            app.ContinousArtefactRejectionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Artefact_Rejection_Continous_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ContinousArtefactRejectionUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ContinousArtefactRejectionUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ContinousArtefactRejectionUIFigure)
        end
    end
end