classdef Channel_Deletion_Selection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ChannelDeletionWindowUIFigure  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        RightPanel                     matlab.ui.container.Panel
        Image                          matlab.ui.control.Image
        NeuromodLabel                  matlab.ui.control.Label
        ChannelDeletionWindowLabel     matlab.ui.control.Label
        CloseButton                    matlab.ui.control.Button
        SaveSelectionandaddtopipelineButton  matlab.ui.control.Button
        ChanneltoDelete                matlab.ui.control.EditField
        ChanneltoDeleteMatlabfunctionsonlyexample110EditFieldLabel  matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        PreprocessingApp = []; % Description
        Userinput = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, PreprocessingApp)
            movegui(app.ChannelDeletionWindowUIFigure, 'center');

            app.PreprocessingApp = PreprocessingApp;
        end

        % Value changed function: ChanneltoDelete
        function ChanneltoDeleteValueChanged(app, event)
            value = app.ChanneltoDelete.Value;
            
            app.Userinput = eval(value);
            
        end

        % Button pushed function: SaveSelectionandaddtopipelineButton
        function SaveSelectionandaddtopipelineButtonPushed(app, event)
            Input = eval(app.ChanneltoDelete.Value);
            
            if ~isa(Input,'double') && ~isa(Input,'single')
                msgbox("Error: Only numbers allowed!");
                return;
            end
            
            if Input(end) > size(app.PreprocessingApp.Mainapp.Data.Raw,1) || Input(1) > size(app.PreprocessingApp.Mainapp.Data.Raw,1) || Input(1) >= Input(end) || Input(1) <= 0
                msgbox("Format error! Only numbers starting from one or smaller or equal to the channelnumber are allowed");
                return;
            end

            if Input(1) ~= 1 && Input(end) ~= size(app.PreprocessingApp.Mainapp.Data.Raw,1)
                msgbox("Warning! Only consecutive channel numbers starting with the first one or ending with the last one can be deleted!");
                return;
            end

            app.PreprocessingApp.Info.ChannelDeletion = app.Userinput;

            %% Add Selection to the pipeline. Filterorder, cutoff and all
            %% other relevant parameters are extracted and saved here (bc multiple filter can be selected and settings have to be saved in between)!
            % Additionally checks logic if user wants to add two or more of the following
            % three filters: Low Pass, High Pass, Narrwoband. This is
            % rejected since it makes no sense. Instead, the existing added
            % filter type is replace by the most recent one added.
            [app.PreprocessingApp.Info,app.PreprocessingApp.PreprocessingSteps,texttoshow] = Preprocess_Module_Construct_Pipeline("ChannelDeletion",app.PreprocessingApp.Info,app.PreprocessingApp.PreprocessingSteps,0,[],[],[],[],[],[],app.PreprocessingApp.Mainapp.Data.Info.NativeSamplingRate);
            
            if ~isempty(texttoshow)
                app.PreprocessingApp.TextArea.Value = texttoshow;
            end
            
            %[app.PreprocessingApp.Info,app.PreprocessingApp.PreprocessingSteps] = Preprocess_Construct_Pipeline(app.PreprocessingApp,"ChannelDeletion",app.PreprocessingApp.Info,app.PreprocessingApp.PreprocessingSteps,0);

            delete(app);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ChannelDeletionWindowUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {269, 269};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ChannelDeletionWindowUIFigure = uifigure('Visible', 'off');
            app.ChannelDeletionWindowUIFigure.Name = 'Channel Deletion Window';
            app.ChannelDeletionWindowUIFigure.Icon = 'Logo.png';
            app.ChannelDeletionWindowUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.ChannelDeletionWindowUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.ChanneltoDeleteMatlabfunctionsonlyexample110EditFieldLabel = uilabel(app.RightPanel);
            app.ChanneltoDeleteMatlabfunctionsonlyexample110EditFieldLabel.Text = {'Channel to Delete'; '(Matlab functions only)'; 'example.: 1:10'};
            app.ChanneltoDelete = uieditfield(app.RightPanel, 'text');
            app.ChanneltoDelete.ValueChangedFcn = createCallbackFcn(app, @ChanneltoDeleteValueChanged, true);
            app.SaveSelectionandaddtopipelineButton = uibutton(app.RightPanel, 'push');
            app.SaveSelectionandaddtopipelineButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSelectionandaddtopipelineButtonPushed, true);
            app.SaveSelectionandaddtopipelineButton.Text = {'Save Selection and '; 'add to pipeline'};
            app.CloseButton = uibutton(app.RightPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.ChannelDeletionWindowLabel = uilabel(app.RightPanel);
            app.ChannelDeletionWindowLabel.Text = 'Channel Deletion Window';
            app.NeuromodLabel = uilabel(app.RightPanel);
            app.NeuromodLabel.Text = 'Neuromod';
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = 'Logo.png';
            % Show the figure after all components are created
            app.ChannelDeletionWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Channel_Deletion_Selection(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ChannelDeletionWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ChannelDeletionWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ChannelDeletionWindowUIFigure)
        end
    end
end