classdef Cut_Recording_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CutRecordingTimeUIFigure      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        RightPanel                    matlab.ui.container.Panel
        Image                         matlab.ui.control.Image
        NeuromodLabel                 matlab.ui.control.Label
        CutRecordingTimeWindowLabel   matlab.ui.control.Label
        CloseButton                   matlab.ui.control.Button
        SaveselectionandaddtopipelineButton  matlab.ui.control.Button
        CutTimeOptionsPanel           matlab.ui.container.Panel
        sspecifiedamountofsecondsdeletedLabel  matlab.ui.control.Label
        CutStartofRecordingLabel      matlab.ui.control.Label
        EndTimespecifiedamountdeletedLabel  matlab.ui.control.Label
        CutEndofRecordingLabel        matlab.ui.control.Label
        TimetocutbeforeendsEditField  matlab.ui.control.EditField
        TimetocutbeforeendsEditFieldLabel  matlab.ui.control.Label
        TimetocutfromstartsEditField  matlab.ui.control.EditField
        TimetocutLabel                matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        PreprocessingApp = []; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, PreprocessingApp)
            movegui(app.CutRecordingTimeUIFigure, 'center');

            app.PreprocessingApp = PreprocessingApp;

        end

        % Button pushed function: SaveselectionandaddtopipelineButton
        function SaveselectionandaddtopipelineButtonPushed(app, event)
            if ~isempty(app.TimetocutfromstartsEditField.Value)
                app.PreprocessingApp.Info.CutStart = str2double(app.TimetocutfromstartsEditField.Value);
                [app.PreprocessingApp.Info,app.PreprocessingApp.PreprocessingSteps,texttoshow] = Preprocess_Module_Construct_Pipeline("CutStart",app.PreprocessingApp.Info,app.PreprocessingApp.PreprocessingSteps,0,[],[],[],[],[],[],app.PreprocessingApp.Mainapp.Data.Info.NativeSamplingRate);
                
            end
            if ~isempty(app.TimetocutbeforeendsEditField.Value)
                app.PreprocessingApp.Info.CutEnd = str2double(app.TimetocutbeforeendsEditField.Value);
                [app.PreprocessingApp.Info,app.PreprocessingApp.PreprocessingSteps,texttoshow] = Preprocess_Module_Construct_Pipeline("CutEnd",app.PreprocessingApp.Info,app.PreprocessingApp.PreprocessingSteps,0,[],[],[],[],[],[],app.PreprocessingApp.Mainapp.Data.Info.NativeSamplingRate);
            end

            if ~isempty(texttoshow)
                app.PreprocessingApp.TextArea.Value = texttoshow;
            end

            delete(app);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: TimetocutfromstartsEditField
        function TimetocutfromstartsEditFieldValueChanged(app, event)
            [app.TimetocutfromstartsEditField.Value] = Utility_SimpleCheckInputs(app.TimetocutfromstartsEditField.Value,"One",strcat(''),0,0);
            
            if isempty(app.TimetocutfromstartsEditField.Value)
                msgbox("Format Error. Entered Value was resetted!");
            end

            if ~isempty(app.TimetocutfromstartsEditField.Value)
                if str2double(app.TimetocutfromstartsEditField.Value)>app.PreprocessingApp.Mainapp.Data.Time(end)
                    msgbox("Error: Entered time range exceeds max time of recording!")
                    app.TimetocutfromstartsEditField.Value = '';
                end
            end
        end

        % Value changed function: TimetocutbeforeendsEditField
        function TimetocutbeforeendsEditFieldValueChanged(app, event)
            [app.TimetocutbeforeendsEditField.Value] = Utility_SimpleCheckInputs(app.TimetocutbeforeendsEditField.Value,"One",strcat(''),0,0);

            if isempty(app.TimetocutbeforeendsEditField.Value)
                msgbox("Format Error. Entered Value was resetted!");
            end

            if ~isempty(app.TimetocutbeforeendsEditField.Value)
                if str2double(app.TimetocutbeforeendsEditField.Value)>app.PreprocessingApp.Mainapp.Data.Time(end)
                    msgbox("Error: Entered time range exceeds max time of recording!")
                    app.TimetocutbeforeendsEditField.Value = '';
                end
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.CutRecordingTimeUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {316, 316};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CutRecordingTimeUIFigure = uifigure('Visible', 'off');
            app.CutRecordingTimeUIFigure.Name = 'Cut Recording Time';
            app.CutRecordingTimeUIFigure.Icon = 'Logo.png';
            app.CutRecordingTimeUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.CutRecordingTimeUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.CutTimeOptionsPanel = uipanel(app.RightPanel);
            app.CutTimeOptionsPanel.Title = 'Cut Time Options';
            app.TimetocutLabel = uilabel(app.CutTimeOptionsPanel);
            app.TimetocutLabel.Text = {'Time to cut'; 'from start [s]'};
            app.TimetocutfromstartsEditField = uieditfield(app.CutTimeOptionsPanel, 'text');
            app.TimetocutfromstartsEditField.ValueChangedFcn = createCallbackFcn(app, @TimetocutfromstartsEditFieldValueChanged, true);
            app.TimetocutbeforeendsEditFieldLabel = uilabel(app.CutTimeOptionsPanel);
            app.TimetocutbeforeendsEditFieldLabel.Text = {'Time to cut '; 'before end [s]'};
            app.TimetocutbeforeendsEditField = uieditfield(app.CutTimeOptionsPanel, 'text');
            app.TimetocutbeforeendsEditField.ValueChangedFcn = createCallbackFcn(app, @TimetocutbeforeendsEditFieldValueChanged, true);
            app.CutEndofRecordingLabel = uilabel(app.CutTimeOptionsPanel);
            app.CutEndofRecordingLabel.Text = {'Cut End of Recording'; ''};
            app.EndTimespecifiedamountdeletedLabel = uilabel(app.CutTimeOptionsPanel);
            app.EndTimespecifiedamountdeletedLabel.Text = '(End Time - specified amount deleted)';
            app.CutStartofRecordingLabel = uilabel(app.CutTimeOptionsPanel);
            app.CutStartofRecordingLabel.Text = {'Cut Start of Recording'; ''};
            app.sspecifiedamountofsecondsdeletedLabel = uilabel(app.CutTimeOptionsPanel);
            app.sspecifiedamountofsecondsdeletedLabel.Text = '(0s + specified amount of seconds deleted)';
            app.SaveselectionandaddtopipelineButton = uibutton(app.RightPanel, 'push');
            app.SaveselectionandaddtopipelineButton.ButtonPushedFcn = createCallbackFcn(app, @SaveselectionandaddtopipelineButtonPushed, true);
            app.SaveselectionandaddtopipelineButton.Text = {'Save selection and '; 'add to pipeline'};
            app.CloseButton = uibutton(app.RightPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.CutRecordingTimeWindowLabel = uilabel(app.RightPanel);
            app.CutRecordingTimeWindowLabel.Text = 'Cut Recording Time Window';
            app.NeuromodLabel = uilabel(app.RightPanel);
            app.NeuromodLabel.Text = 'Neuromod';
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = 'Logo.png';
            % Show the figure after all components are created
            app.CutRecordingTimeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Cut_Recording_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.CutRecordingTimeUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.CutRecordingTimeUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CutRecordingTimeUIFigure)
        end
    end
end