classdef Preprocessing_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PreprocessingWindowUIFigure    matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        NormalizePanel                 matlab.ui.container.Panel
        AddtoPipelineButton_3          matlab.ui.control.Button
        PlotExampleButton_4            matlab.ui.control.Button
        DownsamplingPanel              matlab.ui.container.Panel
        DownsampleFactorEditField      matlab.ui.control.EditField
        DownsampleFactorLabel          matlab.ui.control.Label
        AddtoPipelineButton_2          matlab.ui.control.Button
        PlotExampleButton_2            matlab.ui.control.Button
        FilteringPanel                 matlab.ui.container.Panel
        InspectFilterButton            matlab.ui.control.Button
        FilterOrderEditField           matlab.ui.control.EditField
        FilterOrderEditFieldLabel      matlab.ui.control.Label
        CuttoffFrequencyHzEditField    matlab.ui.control.EditField
        CuttoffFrequencyHzEditFieldLabel  matlab.ui.control.Label
        FilterDirectionDropDown        matlab.ui.control.DropDown
        FilterDirectionDropDownLabel   matlab.ui.control.Label
        FilterTypeDropDown             matlab.ui.control.DropDown
        FilterTypeDropDownLabel        matlab.ui.control.Label
        FilterMethodDropDown           matlab.ui.control.DropDown
        FilterMethodDropDownLabel      matlab.ui.control.Label
        AddtoPipelineButton            matlab.ui.control.Button
        PlotExampleButton              matlab.ui.control.Button
        NeuromodLabel                  matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        PreprocessingWindowLabel       matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        DeleteLastPipelineEntryButton  matlab.ui.control.Button
        StartPipelineButton            matlab.ui.control.Button
        PipelineComponentsLabel        matlab.ui.control.Label
        TextArea                       matlab.ui.control.TextArea
        CloseButton                    matlab.ui.control.Button
        GrandAveragePanel              matlab.ui.container.Panel
        AddtoPipelineButton_4          matlab.ui.control.Button
        PlotExampleButton_3            matlab.ui.control.Button
        OtherUtilitiesPanel            matlab.ui.container.Panel
        StimulationArtefactRejectionButton  matlab.ui.control.Button
        CutStartandEndofRecordingButton_2  matlab.ui.control.Button
        DeleteChannelButton            matlab.ui.control.Button
        TextArea_2                     matlab.ui.control.TextArea
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Mainapp = []; % Description
        PreprocessingSteps = [];
        Info = [] % Temporarily save all preprocessing options until they are added to the mainapp object after execution of all pipeline steps
        PreprocessingFlag = []; % Saves a 1 when preprocessing was already applied in the same instance of the windo. This is to warn the user if he/she wants to start a pipeline again
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            %% Initiate GUI 
            movegui(app.PreprocessingWindowUIFigure, 'center');

            % Save Mainapp object along with Data structure here for easy
            % acces across all gui functions
            app.Mainapp = Mainapp;

            % Predefine Values
            app.CuttoffFrequencyHzEditField.Value = "300";
            app.FilterOrderEditField.Value = "3";
            app.DownsampleFactorEditField.Value = num2str(1000);
            pause(0.2);

            app.TextArea.Value = "No Preprocessing Steps added.";

            app.PreprocessingFlag = 0;

            %% Check whether preprocessing was already applied to the dataset. If so warn the user that data will be overwritten!
            if isfield(app.Mainapp.Data, 'Preprocessed')
                %f = msgbox("Warning! Data was already preprocessed. Existing preprocessed data will be overwritten if pipeline is started!");
                app.TextArea.Value{1} = 'No Preprocessing Steps added. Warning! Data was already preprocessed. Starting Pipeline will overwrite it!';
                app.TextArea.Value{2} = '';
                app.TextArea.Value{3} = 'Already Applied Preprocessing Steps:';
                app.TextArea.Value{4} = '';

                % List of fields to search for
                fieldsToSearch = {'FilterMethod', 'FilterType', 'FilterDirection','FilterOrder','Cutoff','ChannelDeletion','CutEnd','CuStart','GrandAverage','Normalize','DownsampleFactor','DownsampledSampleRate','StimArtefactChannel','TimeAroundStimArtefact'};
                
                % Initialize a cell array to store the text lines
                textLines = {};
                
                % Loop over the fields to search
                for i = 1:length(fieldsToSearch)
                    fieldName = fieldsToSearch{i};
                    
                    % Check if the field exists in the structure
                    if isfield(app.Mainapp.Data.Info, fieldName)
                        % Get the field value
                        fieldValue = app.Mainapp.Data.Info.(fieldName);
                        
                        % Create a string with the field name and value
                        if ischar(fieldValue) || isstring(fieldValue)
                            line = sprintf('%s: %s', fieldName, fieldValue);
                        elseif isnumeric(fieldValue)
                            line = sprintf('%s: %g', fieldName, fieldValue);
                        else
                            line = sprintf('%s: (unsupported type)', fieldName);
                        end
                        
                        % Append the line to the cell array
                        textLines{end+1} = line; %#ok<AGROW>
                    end
                end
                
                % Set the value of the textarea
                for i = 1:length(textLines)
                    app.TextArea.Value{i+4} = textLines{i};
                end
            else
                app.TextArea.Value = "No Preprocessing steps added. Add steps to the pipeline and start it to preprocess your data.";
            end
 
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            if isfield(app,'Mainapp')
                app.Mainapp.PreproWindow = [];
                delete(app);
            else
                delete(app);
            end
        end

        % Button pushed function: PlotExampleButton
        function PlotExampleButtonPushed(app, event)
            [TempInfo,TempPreprocessingSteps,~] = Preprocess_Module_Construct_Pipeline("Filter",[],[],1,app.FilterMethodDropDown.Value,app.FilterTypeDropDown.Value,app.CuttoffFrequencyHzEditField.Value,app.FilterDirectionDropDown.Value,app.FilterOrderEditField.Value,app.DownsampleFactorEditField.Value,app.Mainapp.Data.Info.NativeSamplingRate);
                        
            [~] = Preprocess_Module_Apply_Pipeline (app.Mainapp.Data,app.Mainapp.Data.Info.NativeSamplingRate,TempPreprocessingSteps,1,TempInfo);
        end

        % Value changed function: FilterMethodDropDown
        function FilterMethodDropDownValueChanged(app, event)
            %% Predefined Settings when Filtertype is change i.e. from High to Low Pass filter
            value = app.FilterMethodDropDown.Value;
            if strcmp(value,"Low-Pass")
                app.CuttoffFrequencyHzEditField.Enable = "on";
                app.CuttoffFrequencyHzEditField.Value = "220";
                app.FilterOrderEditField.Value = "3";
                app.FilterDirectionDropDown.Enable = "on";
            elseif strcmp(value,"High-Pass")
                app.CuttoffFrequencyHzEditField.Value = "300";
                app.CuttoffFrequencyHzEditField.Enable = "on";
                app.FilterOrderEditField.Value = "3";
                app.FilterDirectionDropDown.Enable = "on";
            elseif strcmp(value,"Narrowband")
                app.CuttoffFrequencyHzEditField.Value = "10,220";
                app.CuttoffFrequencyHzEditField.Enable = "on";
                app.FilterOrderEditField.Value = "3";
                app.FilterDirectionDropDown.Enable = "on";
            elseif strcmp(value,"Band-Stop")
                app.CuttoffFrequencyHzEditField.Value = "48,52";
                app.CuttoffFrequencyHzEditField.Enable = "on";
                app.FilterOrderEditField.Value = "2";
                app.FilterDirectionDropDown.Enable = "on";
            elseif strcmp(value,"Median Filter")
                app.CuttoffFrequencyHzEditField.Enable = "off";
                app.FilterOrderEditField.Value = "3";
                app.FilterDirectionDropDown.Enable = "off";
            end
        end

        % Button pushed function: AddtoPipelineButton
        function AddtoPipelineButtonPushed(app, event)
            %% Add Selection to the pipeline. Filterorder, cutoff and all
            %% other relevant parameters are extracted and saved here (bc multiple filter can be selected and settings have to be saved in between)!
            % Additionally checks logic if user wants to add two or more of the following
            % three filters: Low Pass, High Pass, Narrwoband. This is
            % rejected since it makes no sense. Instead, the existing added
            % filter type is replace by the most recent one added.
            [app.Info,app.PreprocessingSteps,InfoText] = Preprocess_Module_Construct_Pipeline("Filter",app.Info,app.PreprocessingSteps,0,app.FilterMethodDropDown.Value,app.FilterTypeDropDown.Value,app.CuttoffFrequencyHzEditField.Value,app.FilterDirectionDropDown.Value,app.FilterOrderEditField.Value,app.DownsampleFactorEditField.Value,app.Mainapp.Data.Info.NativeSamplingRate);
            if ~isempty(InfoText)
                app.TextArea.Value = InfoText;
            end
        end

        % Button pushed function: AddtoPipelineButton_2
        function AddtoPipelineButton_2Pushed(app, event)
            % Add Selection to the pipeline. Filterorder, cutoff and all
            %% other relevant parameters are extracted and saved here (bc multiple filter can be selected and settings have to be saved in between)!
            % Additionally checks logic if user wants to add two or more of the following
            % three filters: Low Pass, High Pass, Narrwoband. This is
            % rejected since it makes no sense. Instead, the existing added
            % filter type is replace by the most recent one added.
            [app.Info,app.PreprocessingSteps,InfoText] = Preprocess_Module_Construct_Pipeline("Downsample",app.Info,app.PreprocessingSteps,0,app.FilterMethodDropDown.Value,app.FilterTypeDropDown.Value,app.CuttoffFrequencyHzEditField.Value,app.FilterDirectionDropDown.Value,app.FilterOrderEditField.Value,app.DownsampleFactorEditField.Value,app.Mainapp.Data.Info.NativeSamplingRate);
            
            if app.Info.DownsampledSampleRate ~= str2double(app.DownsampleFactorEditField.Value)
                app.DownsampleFactorEditField.Value = num2str(app.Info.DownsampledSampleRate);
            end

            if ~isempty(InfoText)
                app.TextArea.Value = InfoText;
            end
        end

        % Button pushed function: AddtoPipelineButton_3
        function AddtoPipelineButton_3Pushed(app, event)
            % Add Selection to the pipeline. Filterorder, cutoff and all
            %% other relevant parameters are extracted and saved here (bc multiple filter can be selected and settings have to be saved in between)!
            % Additionally checks logic if user wants to add two or more of the following
            % three filters: Low Pass, High Pass, Narrwoband. This is
            % rejected since it makes no sense. Instead, the existing added
            % filter type is replace by the most recent one added.
            [app.Info,app.PreprocessingSteps,InfoText] = Preprocess_Module_Construct_Pipeline("Normalize",app.Info,app.PreprocessingSteps,0,app.FilterMethodDropDown.Value,app.FilterTypeDropDown.Value,app.CuttoffFrequencyHzEditField.Value,app.FilterDirectionDropDown.Value,app.FilterOrderEditField.Value,app.DownsampleFactorEditField.Value,app.Mainapp.Data.Info.NativeSamplingRate);
            if ~isempty(InfoText)
                app.TextArea.Value = InfoText;
            end
        end

        % Button pushed function: AddtoPipelineButton_4
        function AddtoPipelineButton_4Pushed(app, event)
            % Add Selection to the pipeline. Filterorder, cutoff and all
            %% other relevant parameters are extracted and saved here (bc multiple filter can be selected and settings have to be saved in between)!
            % Additionally checks logic if user wants to add two or more of the following
            % three filters: Low Pass, High Pass, Narrwoband. This is
            % rejected since it makes no sense. Instead, the existing added
            % filter type is replace by the most recent one added.
            [app.Info,app.PreprocessingSteps,InfoText] = Preprocess_Module_Construct_Pipeline("GrandAverage",app.Info,app.PreprocessingSteps,0,app.FilterMethodDropDown.Value,app.FilterTypeDropDown.Value,app.CuttoffFrequencyHzEditField.Value,app.FilterDirectionDropDown.Value,app.FilterOrderEditField.Value,app.DownsampleFactorEditField.Value,app.Mainapp.Data.Info.NativeSamplingRate);
            if ~isempty(InfoText)
                app.TextArea.Value = InfoText;
            end
        end

        % Button pushed function: StartPipelineButton
        function StartPipelineButtonPushed(app, event)
            % If preprocesing already applied in the same instance of this
            % window, warn the user that previous data will be overwritten
                        
            if isempty(app.PreprocessingSteps)
                f = msgbox("Error: No pipeline components added");
                return;
            end

            PreviousChannelDeletetion = 0;
            % If Channel were deleted in previous preprocessing flag this
            % to reset main window parameter after + only allow for prepro
            % view if prepro was generated
            if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                PreviousChannelDeletetion = 1;
            end

            % Set the flag to 1 that indicates that preproc. was applied in
            % this window instance
            app.PreprocessingFlag = 1;

            if isfield(app.Info,'ChannelDeletion')
                ChannelDeletion = app.Info.ChannelDeletion;
            else
                ChannelDeletion = [];
            end

            if isfield(app.Info,'CutStart') || isfield(app.Info,'CutEnd')
                app.Mainapp.CurrentTimePoints = 1;
            end
            %% First overwrite old preprocessing infos from structure if data was already preprocesseds --> Old preprocessing is overwritten!
            [app.Mainapp.Data,app.Info,app.TextArea] = Preprocess_Module_Delete_Old_Settings(app.Mainapp.Data,app.Info,app.PreprocessingSteps,ChannelDeletion,app.TextArea);
            
            %% Loop over Preprocessing Steps. app Input necessary to extract filterorder, cutoff and so on from app.Info object saving the prepr. parameters. 
            % Preprocessing itself can also run without this if all the
            % necessary parameters are defined manually (see in function for further detail)
            
            f = [];

            [app.Mainapp.Data] = Preprocess_Module_Apply_Pipeline (app.Mainapp.Data,app.Mainapp.Data.Info.NativeSamplingRate,app.PreprocessingSteps,0,app.Info,ChannelDeletion,app.TextArea);
            
            % account for channeldeltion in currently actice channel by
            % just overwriting with all new active channel
            if isfield(app.Info,'ChannelDeletion')
                app.Mainapp.ActiveChannel = app.Mainapp.Data.Info.ProbeInfo.ActiveChannel;

                if isfield(app.Mainapp.Data.Info.ProbeInfo,'CompleteAreaNames')
                    BrainAreaInfo.CompleteAreaNames = app.Mainapp.Data.Info.ProbeInfo.CompleteAreaNames;
                    BrainAreaInfo.ShortAreaNames = app.Mainapp.Data.Info.ProbeInfo.ShortAreaNames;
                    BrainAreaInfo.AreaDistanceFromTip = app.Mainapp.Data.Info.ProbeInfo.AreaDistanceFromTip;
                else
                    BrainAreaInfo = [];
                end

                if ~isempty(app.Mainapp.ProbeViewWindowHandle)
                    if str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows) == 1
                        
                        app.Mainapp.ProbeViewWindowHandle.FirstZoomChannel = str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)-32;            

                        Utility_Plot_Interactive_Probe_View(app.Mainapp.ProbeViewWindowHandle.UIAxes,app.Mainapp.Data.Info.ChannelSpacing,str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel),str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows),str2double(app.Mainapp.Data.Info.ProbeInfo.HorOffset),str2double(app.Mainapp.Data.Info.ProbeInfo.VertOffset),app.Mainapp.Data.Info.Channelorder,app.Mainapp.ActiveChannel,app.Mainapp.ProbeViewWindowHandle.FirstZoomChannel,1,BrainAreaInfo,app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.Mainapp.ProbeViewWindowHandle.ShowChannelSpacingCheckBox.Value,1,1,app.Mainapp.ActiveChannel,app.Mainapp.Data.Info.ProbeInfo.OffSetRows,[],app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel,app.Mainapp.Data.Info.ProbeInfo.SwitchLeftRightChannel)
                    else
                        app.Mainapp.ProbeViewWindowHandle.FirstZoomChannel = str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel);
                        Utility_Plot_Interactive_Probe_View(app.Mainapp.ProbeViewWindowHandle.UIAxes,app.Mainapp.Data.Info.ChannelSpacing,str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel),str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows),str2double(app.Mainapp.Data.Info.ProbeInfo.HorOffset),str2double(app.Mainapp.Data.Info.ProbeInfo.VertOffset),app.Mainapp.Data.Info.Channelorder,app.Mainapp.ActiveChannel,app.Mainapp.ProbeViewWindowHandle.FirstZoomChannel,1,BrainAreaInfo,app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.Mainapp.ProbeViewWindowHandle.ShowChannelSpacingCheckBox.Value,1,1,app.Mainapp.Data.Info.ProbeInfo.OffSetRows,[],app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel,app.Mainapp.Data.Info.ProbeInfo.SwitchLeftRightChannel)
                    end
                end
            end

            if isfield(app.Mainapp.Data,'Events')
                if ~isempty(app.Mainapp.Data.Events)
                    for i = 1:length(app.Mainapp.Data.Events)
                        texttoshow{i} =  app.Mainapp.Data.Info.EventChannelNames{i};
                    end
                    app.Mainapp.EventChannelDropDown.Items = texttoshow;
                end
            end

            %% Update Textbox "Recording Information" in Main window with new preprocessing infos
            Utility_Show_Info_Loaded_Data(app.Mainapp);

            [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);

            %% Wrap up
            
            app.PreprocessingSteps = [];
            app.Info = [];
            app.TextArea.Value{1} = 'Preprocessing Finished';
            app.TextArea.Value{2} = '';
            app.TextArea.Value{3} = 'Applied Preprocessing Steps:';
            app.TextArea.Value{4} = '';

            % List of fields to search for
            fieldsToSearch = {'FilterMethod', 'FilterType', 'FilterDirection','FilterOrder','Cutoff','ChannelDeletion','CutEnd','CutStart','GrandAverage','Normalize','DownsampleFactor','DownsampledSampleRate','TimeAroundStimArtefact'};
            
            % Initialize a cell array to store the text lines
            textLines = {};
            
            % Loop over the fields to search
            for i = 1:length(fieldsToSearch)
                fieldName = fieldsToSearch{i};
                
                % Check if the field exists in the structure
                if isfield(app.Mainapp.Data.Info, fieldName)
                    % Get the field value
                    fieldValue = app.Mainapp.Data.Info.(fieldName);
                    
                    % Create a string with the field name and value
                    if ischar(fieldValue) || isstring(fieldValue)
                        line = sprintf('%s: %s', fieldName, fieldValue);
                    elseif isnumeric(fieldValue)
                        if length(fieldValue) == 2
                            line = sprintf('%s: %d %d', fieldName, fieldValue(1),fieldValue(2));
                        else
                            line = sprintf('%s: %g', fieldName, fieldValue);
                        end
                    else
                        line = sprintf('%s: (unsupported type)', fieldName);
                    end
                    
                    % Append the line to the cell array
                    textLines{end+1} = line; %#ok<AGROW>
                end
            end
            
            % Set the value of the textarea
            for i = 1:length(textLines)
                app.TextArea.Value{i+4} = textLines{i};
            end


            if strcmp(app.Mainapp.DropDown.Value,'Preprocessed Data')
                if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                    TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                    % Calculate the absolute differences
                    differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                    
                    % Find the index of the minimum difference
                    [~, app.Mainapp.CurrentTimePoints] = min(differences);
                end
            end
            
            app.Mainapp.CurrentTimePoints = 1; % Standard TIme in seconds
            
            a = find(app.Mainapp.TimeRangeViewBox.Value=='s');
            CurrentTimeSpan = str2double(app.Mainapp.TimeRangeViewBox.Value(1:a(1)-1));

            if CurrentTimeSpan>app.Mainapp.Data.Time(end)
                app.Mainapp.TimeLimit = [0.0333,3]; % sets maximum and minimum amount of time shown in Main Window Plot
                TimeRangeText = strcat(num2str(1),"s");
                app.Mainapp.TimeRangeViewBox.Value = TimeRangeText;
            end

            Organize_Reset_Main_Plot(app.Mainapp,1,1,1,1,1,"Initial");
            
            Organize_Delete_All_Open_Windows(app.Mainapp,0);

        end

        % Button pushed function: PlotExampleButton_2
        function PlotExampleButton_2Pushed(app, event)
            
            [TempInfo,TempPreprocessingSteps,~] = Preprocess_Module_Construct_Pipeline("Downsample",[],[],1,app.FilterMethodDropDown.Value,app.FilterTypeDropDown.Value,app.CuttoffFrequencyHzEditField.Value,app.FilterDirectionDropDown.Value,app.FilterOrderEditField.Value,app.DownsampleFactorEditField.Value,app.Mainapp.Data.Info.NativeSamplingRate);

            [~] = Preprocess_Module_Apply_Pipeline (app.Mainapp.Data,app.Mainapp.Data.Info.NativeSamplingRate,TempPreprocessingSteps,1,TempInfo);

        end

        % Button pushed function: PlotExampleButton_4
        function PlotExampleButton_4Pushed(app, event)
            [TempInfo,TempPreprocessingSteps,~] = Preprocess_Module_Construct_Pipeline("Normalize",[],[],1,app.FilterMethodDropDown.Value,app.FilterTypeDropDown.Value,app.CuttoffFrequencyHzEditField.Value,app.FilterDirectionDropDown.Value,app.FilterOrderEditField.Value,app.DownsampleFactorEditField.Value,app.Mainapp.Data.Info.NativeSamplingRate);

            [~] = Preprocess_Module_Apply_Pipeline (app.Mainapp.Data,app.Mainapp.Data.Info.NativeSamplingRate,TempPreprocessingSteps,1,TempInfo);
        end

        % Button pushed function: PlotExampleButton_3
        function PlotExampleButton_3Pushed(app, event)
            [TempInfo,TempPreprocessingSteps,~] = Preprocess_Module_Construct_Pipeline("GrandAverage",[],[],1,app.FilterMethodDropDown.Value,app.FilterTypeDropDown.Value,app.CuttoffFrequencyHzEditField.Value,app.FilterDirectionDropDown.Value,app.FilterOrderEditField.Value,app.DownsampleFactorEditField.Value,app.Mainapp.Data.Info.NativeSamplingRate);
            
            [~] = Preprocess_Module_Apply_Pipeline (app.Mainapp.Data,app.Mainapp.Data.Info.NativeSamplingRate,TempPreprocessingSteps,1,TempInfo);
        end

        % Button pushed function: DeleteLastPipelineEntryButton
        function DeleteLastPipelineEntryButtonPushed(app, event)
            if isempty(app.PreprocessingSteps)
                f = msgbox("Error: No pipeline components added");
                return;
            end

            if strcmp(app.PreprocessingSteps(end),"High-Pass") || strcmp(app.PreprocessingSteps(end),"Low-Pass") || strcmp(app.PreprocessingSteps(end),"Narrowband") 
                if isfield(app.Info,'FilterMethod')
                    % Fields to delete
                    fieldsToDelete = {'Cutoff', 'FilterOrder', 'FilterMethod', 'FilterType', 'FilterDirection'};
                    % Delete fields
                    app.Info = rmfield(app.Info, fieldsToDelete);
                end
                app.PreprocessingSteps(end) = [];
            elseif strcmp(app.PreprocessingSteps(end),"Median-Filter") 
                if isfield(app.Info,'MedianFilterMethod')
                    % Fields to delete
                    fieldsToDelete = {'MedianFilterOrder', 'MedianFilterMethod'};
                    % Delete fields
                    app.Info = rmfield(app.Info, fieldsToDelete);
                end
                app.PreprocessingSteps(end) = [];
            elseif strcmp(app.PreprocessingSteps(end),"Band-Stop")
                if isfield(app.Info,'BandStopFilterMethod')
                    % Fields to delete
                    fieldsToDelete = {'BandStopCutoff', 'BandStopFilterOrder', 'BandStopFilterMethod', 'BandStopFilterType', 'BandStopFilterDirection'};
                    % Delete fields
                    app.Info = rmfield(app.Info, fieldsToDelete);
                end
                app.PreprocessingSteps(end) = [];
            elseif strcmp(app.PreprocessingSteps(end),"Downsampling")
                fieldsToDeleteInfo = {'DownsampleFactor','DownsampledSampleRate'};
                app.Info = rmfield(app.Info, fieldsToDeleteInfo);
                app.PreprocessingSteps(end) = [];
            elseif strcmp(app.PreprocessingSteps(end),"Normalize")
                fieldsToDelete = {'Normalize'};
                app.Info = rmfield(app.Info, fieldsToDelete);
                app.PreprocessingSteps(end) = [];
            elseif strcmp(app.PreprocessingSteps(end),"GrandAverage") 
                fieldsToDelete = {'GrandAverage'};
                app.Info = rmfield(app.Info, fieldsToDelete);
                app.PreprocessingSteps(end) = [];
            elseif strcmp(app.PreprocessingSteps(end),"ChannelDeletion") 
                fieldsToDelete = {'ChannelDeletion'};
                app.Info = rmfield(app.Info, fieldsToDelete);
                app.PreprocessingSteps(end) = [];
            elseif strcmp(app.PreprocessingSteps(end),"CutStart") 
                fieldsToDelete = {'CutStart'};
                app.Info = rmfield(app.Info, fieldsToDelete);
                app.PreprocessingSteps(end) = [];
            elseif strcmp(app.PreprocessingSteps(end),"CutEnd") 
                fieldsToDelete = {'CutEnd'};
                app.Info = rmfield(app.Info, fieldsToDelete);
                app.PreprocessingSteps(end) = [];
            end

            if ~isempty(app.PreprocessingSteps)
                % Temporarily delete channeldeletion settings (channel
                % numbers) to be able to show the text in the window -->
                % has to be fixed
                if isfield(app.Info,"ChannelDeletion")
                    TempInfo = app.Info;
                    fieldsToDelete = {'ChannelDeletion'};
                    % Delete fields
                    TempInfo = rmfield(TempInfo, fieldsToDelete);
                    % Get the field names of the structure
                    fieldNames = fieldnames(TempInfo);
                else
                    % Get the field names of the structure
                    fieldNames = fieldnames(app.Info);
                end
                
                % Convert the structure fields into a cell array of strings
                structValues = cell(size(fieldNames));
                for i = 1:numel(fieldNames)
                    structValues{i} = string(app.Info.(fieldNames{i}));
                end
                
                % Combine the original string array with the structure values
                Texttoshow = [app.PreprocessingSteps;"";"Pipeline Parameter:"; structValues];
    
                app.TextArea.Value = Texttoshow;
    
            else
                Texttoshow = "No Pipeline Components selected. Add one and start pipeline";
                app.TextArea.Value = Texttoshow;
            end
        end

        % Value changed function: FilterOrderEditField
        function FilterOrderEditFieldValueChanged(app, event)
            [app.FilterOrderEditField.Value] = Utility_SimpleCheckInputs(app.FilterOrderEditField.Value,"One",'3',0,0);

        end

        % Value changed function: DownsampleFactorEditField
        function DownsampleFactorEditFieldValueChanged(app, event)

            [app.DownsampleFactorEditField.Value] = Utility_SimpleCheckInputs(app.DownsampleFactorEditField.Value,"One",'1000',0,0);
            
            downsamplerate = str2double(app.DownsampleFactorEditField.Value);
            if downsamplerate>app.Mainapp.Data.Info.NativeSamplingRate
                msgbox("Error: Can not be higher than native samplerate!")
                return;
            end

        end

        % Value changed function: CuttoffFrequencyHzEditField
        function CuttoffFrequencyHzEditFieldValueChanged(app, event)
             if strcmp(app.FilterMethodDropDown.Value,"High-Pass") || strcmp(app.FilterMethodDropDown.Value,"Low-Pass")
                [app.CuttoffFrequencyHzEditField.Value] = Utility_SimpleCheckInputs(app.CuttoffFrequencyHzEditField.Value,"One",'300',0,0);
             elseif strcmp(app.FilterMethodDropDown.Value,"Narrowband") || strcmp(app.FilterMethodDropDown.Value,"Band-Stop")
                [app.CuttoffFrequencyHzEditField.Value] = Utility_SimpleCheckInputs(app.CuttoffFrequencyHzEditField.Value,"Two",'48,52',0,0);
             end
        end

        % Button pushed function: DeleteChannelButton
        function DeleteChannelButtonPushed(app, event)
            Channel_Deletion_Selection(app);
        end

        % Button pushed function: CutStartandEndofRecordingButton_2
        function CutStartandEndofRecordingButton_2Pushed(app, event)
            
            Cut_Recording_Window(app);
        end

        % Button pushed function: StimulationArtefactRejectionButton
        function StimulationArtefactRejectionButtonPushed(app, event)
            if isfield(app.Mainapp.Data,'Events')
                if ~isempty(app.Mainapp.Data.Events)

                    if isempty(app.Mainapp.ProbeViewWindowHandle) || ~isprop(app.Mainapp.ProbeViewWindowHandle,'ProbeViewUIFigure')
                        app.Mainapp.ProbeViewWindowHandle = Probe_View_Window(app.Mainapp,'MainWindow');
                    end

                    if ~isempty(app.Mainapp.ProbeViewWindowHandle) && isprop(app.Mainapp.ProbeViewWindowHandle,'ProbeViewUIFigure') % Add option to probe view when available
                        AlreadyIn = 0;
                        for i = 1:length(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                            if strcmp(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Prepro Artefact Rejection')
                                AlreadyIn = 1;
                            end
                        end
                        if AlreadyIn == 0
                            app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{end+1} = 'Prepro Artefact Rejection';
                        end 
                    end
                
                    app.Mainapp.PreproArtefactRejection = Artefact_Rejection_Continous_Window(app);

                else
                    msgbox("Error: No event channel found which are necessary to determine stimulation time points.")
                end
            else
                msgbox("Error: No event channel found which are necessary to determine stimulation time points.")
            end
        end

        % Button pushed function: InspectFilterButton
        function InspectFilterButtonPushed(app, event)
            [TempInfo,TempPreprocessingSteps,~] = Preprocess_Module_Construct_Pipeline("Filter",[],[],1,app.FilterMethodDropDown.Value,app.FilterTypeDropDown.Value,app.CuttoffFrequencyHzEditField.Value,app.FilterDirectionDropDown.Value,app.FilterOrderEditField.Value,app.DownsampleFactorEditField.Value,app.Mainapp.Data.Info.NativeSamplingRate);
            
            TempInfo.FilterKernel = 1;

            [~] = Preprocess_Module_Apply_Pipeline (app.Mainapp.Data,app.Mainapp.Data.Info.NativeSamplingRate,TempPreprocessingSteps,1,TempInfo);
        end

        % Close request function: PreprocessingWindowUIFigure
        function PreprocessingWindowUIFigureCloseRequest(app, event)
            if isfield(app,'Mainapp')
                app.Mainapp.PreproWindow = [];
                delete(app);
            else
                delete(app);
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.PreprocessingWindowUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {463, 463};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {436, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PreprocessingWindowUIFigure = uifigure('Visible', 'off');
            app.PreprocessingWindowUIFigure.Name = 'Preprocessing Window';
            app.PreprocessingWindowUIFigure.Icon = 'Logo.png';
            app.PreprocessingWindowUIFigure.CloseRequestFcn = createCallbackFcn(app, @PreprocessingWindowUIFigureCloseRequest, true);
            app.PreprocessingWindowUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.PreprocessingWindowUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.PreprocessingWindowLabel = uilabel(app.LeftPanel);
            app.PreprocessingWindowLabel.Text = 'Preprocessing Window';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodLabel = uilabel(app.LeftPanel);
            app.NeuromodLabel.Text = 'Neuromod';
            app.FilteringPanel = uipanel(app.LeftPanel);
            app.FilteringPanel.Title = 'Filtering';
            app.PlotExampleButton = uibutton(app.FilteringPanel, 'push');
            app.PlotExampleButton.ButtonPushedFcn = createCallbackFcn(app, @PlotExampleButtonPushed, true);
            app.PlotExampleButton.Text = 'Plot Example';
            app.AddtoPipelineButton = uibutton(app.FilteringPanel, 'push');
            app.AddtoPipelineButton.ButtonPushedFcn = createCallbackFcn(app, @AddtoPipelineButtonPushed, true);
            app.AddtoPipelineButton.Text = 'Add to Pipeline';
            app.FilterMethodDropDownLabel = uilabel(app.FilteringPanel);
            app.FilterMethodDropDownLabel.Text = 'Filter Method';
            app.FilterMethodDropDown = uidropdown(app.FilteringPanel);
            app.FilterMethodDropDown.Items = {'High-Pass', 'Low-Pass', 'Narrowband', 'Band-Stop', 'Median Filter'};
            app.FilterMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterMethodDropDownValueChanged, true);
            app.FilterMethodDropDown.Value = 'High-Pass';
            app.FilterTypeDropDownLabel = uilabel(app.FilteringPanel);
            app.FilterTypeDropDownLabel.Text = 'Filter Type';
            app.FilterTypeDropDown = uidropdown(app.FilteringPanel);
            app.FilterTypeDropDown.Items = {'Butterworth IR', 'FIR-1', 'Firls'};
            app.FilterTypeDropDown.Value = 'Butterworth IR';
            app.FilterDirectionDropDownLabel = uilabel(app.FilteringPanel);
            app.FilterDirectionDropDownLabel.Text = 'Filter Direction';
            app.FilterDirectionDropDown = uidropdown(app.FilteringPanel);
            app.FilterDirectionDropDown.Items = {'Forward', 'Reverse', 'Zero-phase forward and reverse', 'Zero-phase reverse and forward'};
            app.FilterDirectionDropDown.Value = 'Zero-phase forward and reverse';
            app.CuttoffFrequencyHzEditFieldLabel = uilabel(app.FilteringPanel);
            app.CuttoffFrequencyHzEditFieldLabel.Text = {'Cuttoff Frequency'; '[Hz]'};
            app.CuttoffFrequencyHzEditField = uieditfield(app.FilteringPanel, 'text');
            app.CuttoffFrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @CuttoffFrequencyHzEditFieldValueChanged, true);
            app.FilterOrderEditFieldLabel = uilabel(app.FilteringPanel);
            app.FilterOrderEditFieldLabel.Text = 'Filter Order';
            app.FilterOrderEditField = uieditfield(app.FilteringPanel, 'text');
            app.FilterOrderEditField.ValueChangedFcn = createCallbackFcn(app, @FilterOrderEditFieldValueChanged, true);
            app.FilterOrderEditField.Value = '3';
            app.InspectFilterButton = uibutton(app.FilteringPanel, 'push');
            app.InspectFilterButton.ButtonPushedFcn = createCallbackFcn(app, @InspectFilterButtonPushed, true);
            app.InspectFilterButton.Text = 'Inspect Filter';
            app.DownsamplingPanel = uipanel(app.LeftPanel);
            app.DownsamplingPanel.Title = 'Downsampling';
            app.PlotExampleButton_2 = uibutton(app.DownsamplingPanel, 'push');
            app.PlotExampleButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotExampleButton_2Pushed, true);
            app.PlotExampleButton_2.Text = 'Plot Example';
            app.AddtoPipelineButton_2 = uibutton(app.DownsamplingPanel, 'push');
            app.AddtoPipelineButton_2.ButtonPushedFcn = createCallbackFcn(app, @AddtoPipelineButton_2Pushed, true);
            app.AddtoPipelineButton_2.Text = 'Add to Pipeline';
            app.DownsampleFactorLabel = uilabel(app.DownsamplingPanel);
            app.DownsampleFactorLabel.Text = {'New Sampling Rate'; '(Hz)'};
            app.DownsampleFactorEditField = uieditfield(app.DownsamplingPanel, 'text');
            app.DownsampleFactorEditField.ValueChangedFcn = createCallbackFcn(app, @DownsampleFactorEditFieldValueChanged, true);
            app.NormalizePanel = uipanel(app.LeftPanel);
            app.NormalizePanel.Title = 'Normalize';
            app.PlotExampleButton_4 = uibutton(app.NormalizePanel, 'push');
            app.PlotExampleButton_4.ButtonPushedFcn = createCallbackFcn(app, @PlotExampleButton_4Pushed, true);
            app.PlotExampleButton_4.Text = 'Plot Example';
            app.AddtoPipelineButton_3 = uibutton(app.NormalizePanel, 'push');
            app.AddtoPipelineButton_3.ButtonPushedFcn = createCallbackFcn(app, @AddtoPipelineButton_3Pushed, true);
            app.AddtoPipelineButton_3.Text = 'Add to Pipeline';
            app.RightPanel = uipanel(app.GridLayout);
            app.TextArea_2 = uitextarea(app.RightPanel);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Enable = 'off';
            app.TextArea_2.Visible = 'off';
            app.OtherUtilitiesPanel = uipanel(app.RightPanel);
            app.OtherUtilitiesPanel.Title = 'Other Utilities';
            app.DeleteChannelButton = uibutton(app.OtherUtilitiesPanel, 'push');
            app.DeleteChannelButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteChannelButtonPushed, true);
            app.DeleteChannelButton.Text = 'Delete Channel';
            app.CutStartandEndofRecordingButton_2 = uibutton(app.OtherUtilitiesPanel, 'push');
            app.CutStartandEndofRecordingButton_2.ButtonPushedFcn = createCallbackFcn(app, @CutStartandEndofRecordingButton_2Pushed, true);
            app.CutStartandEndofRecordingButton_2.Text = {'Cut Start and End'; 'of Recording'};
            app.StimulationArtefactRejectionButton = uibutton(app.OtherUtilitiesPanel, 'push');
            app.StimulationArtefactRejectionButton.ButtonPushedFcn = createCallbackFcn(app, @StimulationArtefactRejectionButtonPushed, true);
            app.StimulationArtefactRejectionButton.Text = {'Stimulation Artefact '; 'Rejection'};
            app.GrandAveragePanel = uipanel(app.RightPanel);
            app.GrandAveragePanel.Title = 'Grand Average';
            app.PlotExampleButton_3 = uibutton(app.GrandAveragePanel, 'push');
            app.PlotExampleButton_3.ButtonPushedFcn = createCallbackFcn(app, @PlotExampleButton_3Pushed, true);
            app.PlotExampleButton_3.Text = 'Plot Example';
            app.AddtoPipelineButton_4 = uibutton(app.GrandAveragePanel, 'push');
            app.AddtoPipelineButton_4.ButtonPushedFcn = createCallbackFcn(app, @AddtoPipelineButton_4Pushed, true);
            app.AddtoPipelineButton_4.Text = 'Add to Pipeline';
            app.CloseButton = uibutton(app.RightPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.TextArea = uitextarea(app.RightPanel);
            app.TextArea.Editable = 'off';
            app.PipelineComponentsLabel = uilabel(app.RightPanel);
            app.PipelineComponentsLabel.Text = 'Pipeline Components';
            app.StartPipelineButton = uibutton(app.RightPanel, 'push');
            app.StartPipelineButton.ButtonPushedFcn = createCallbackFcn(app, @StartPipelineButtonPushed, true);
            app.StartPipelineButton.Text = 'Start Pipeline';
            app.DeleteLastPipelineEntryButton = uibutton(app.RightPanel, 'push');
            app.DeleteLastPipelineEntryButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteLastPipelineEntryButtonPushed, true);
            app.DeleteLastPipelineEntryButton.Text = {'Delete Last '; 'Pipeline Entry'};
            % Show the figure after all components are created
            app.PreprocessingWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Preprocessing_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.PreprocessingWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.PreprocessingWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PreprocessingWindowUIFigure)
        end
    end
end