classdef Static_Power_Spectrum_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StaticSpectrumWindowUIFigure    matlab.ui.Figure
        SnapshotMenu                    matlab.ui.container.Menu
        SaveSnapshotofWindowMenu        matlab.ui.container.Menu
        PNGMenu                         matlab.ui.container.Menu
        JPGMenu                         matlab.ui.container.Menu
        PlotMenu_2                      matlab.ui.container.Menu
        Show2DimensionalPlotsMenu       matlab.ui.container.Menu
        Show3DimensionalPlotsMenu       matlab.ui.container.Menu
        ChangePlotPropertiesMenu        matlab.ui.container.Menu
        DataPlotMenu                    matlab.ui.container.Menu
        FontSizeMenu                    matlab.ui.container.Menu
        LineWidthDataMenu               matlab.ui.container.Menu
        ColorDataMenu                   matlab.ui.container.Menu
        BackgorundColorMenu             matlab.ui.container.Menu
        XLabelMenu                      matlab.ui.container.Menu
        YLabelMenu                      matlab.ui.container.Menu
        RESETMenu                       matlab.ui.container.Menu
        SaveCostumAppearanceMenu        matlab.ui.container.Menu
        DeleteCostumAppearanceMenu      matlab.ui.container.Menu
        ExportDataMenu                  matlab.ui.container.Menu
        matMenu                         matlab.ui.container.Menu
        txtMenu                         matlab.ui.container.Menu
        csvMenu                         matlab.ui.container.Menu
        CloseButton                     matlab.ui.control.Button
        NeuromodToolboxLabel            matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        StaticSpectrumWindowLabel       matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        FrequencyRangeHzEditField       matlab.ui.control.EditField
        FrequencyRangeHzEditFieldLabel  matlab.ui.control.Label
        DataTypeDropDown                matlab.ui.control.DropDown
        AnalysisTypeDropDownLabel       matlab.ui.control.Label
        AnalysisDropDown                matlab.ui.control.DropDown
        AnalysisDropDownLabel           matlab.ui.control.Label
        ChannelDropDown                 matlab.ui.control.DropDown
        ChannelDropDownLabel            matlab.ui.control.Label
        DataSourceDropDown              matlab.ui.control.DropDown
        DataTypeDropDown_2Label         matlab.ui.control.Label
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        ContextMenu3                    matlab.ui.container.ContextMenu
        ChangeTitleMenu                 matlab.ui.container.Menu
        ChangeXLabelMenu                matlab.ui.container.Menu
        ChangeYLabelMenu                matlab.ui.container.Menu
        ChangeXlimMenu                  matlab.ui.container.Menu
        ChangeYlimMenu                  matlab.ui.container.Menu
        ChangeLegendMenu                matlab.ui.container.Menu
        ChangeFontsizeMenu              matlab.ui.container.Menu
    end

    
    properties (Access = public)
        Mainapp = []; % Description
        BandPower = []
        TwoORThreeD = "TwoD"; % standard 2d plots when possible
        TextArea = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            %% Initiate GUI 
            app.Mainapp = Mainapp;

            movegui(app.StaticSpectrumWindowUIFigure, 'center');
            
            app.ChannelDropDown.Enable = "off";
            app.DataTypeDropDown.Enable = "off";
            app.DataSourceDropDown.Enable = "off";
            app.AnalysisDropDown.Enable = "off";

            %app.TextArea.Value = "Please wait";
            %pause(0.01);

            % Generate Channel nr to be shown based on NRChannel saved
            AllChannels = {};
            if isfield(app.Mainapp.Data,'Raw')
                N = size(app.Mainapp.Data.Raw,1);  
            elseif isfield(app.Mainapp.Data,'Preprocessed')
                N = size(app.Mainapp.Data.Preprocessed,1);  
            end
            
            for i = 1:N
                AllChannels{i} = num2str(i);
            end

            if isfield(app.Mainapp.Data, 'Preprocessed') && isfield(app.Mainapp.Data, 'Raw')
                app.DataSourceDropDown.Items = ["Raw Data","Preprocessed Data"];
                app.DataSourceDropDown.Value = "Raw Data";
            elseif ~isfield(app.Mainapp.Data, 'Preprocessed') && isfield(app.Mainapp.Data, 'Raw')
                app.DataSourceDropDown.Items = "Raw Data";
            elseif isfield(app.Mainapp.Data, 'Preprocessed') && ~isfield(app.Mainapp.Data, 'Raw')
                app.DataSourceDropDown.Items = "Preprocessed Data";
            end

            app.ChannelDropDown.Items = AllChannels;
            SelectedChannel = str2double(app.ChannelDropDown.Value);

            %% Compute and Plot Static Spectrum
            
            [app.Mainapp.CurrentPlotData] = Analyse_Main_Window_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
            
            [app.Mainapp.PowerSpecResults,app.BandPower,~] = Continous_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.Mainapp.PowerSpecResults,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'Just Frequency Bands',app.TwoORThreeD,app.Mainapp.CurrentPlotData);

            app.ChannelDropDown.Enable = "on";
            app.DataTypeDropDown.Enable = "on";
            app.FrequencyRangeHzEditField.Enable = "on";
            app.DataSourceDropDown.Enable = "on";
            app.AnalysisDropDown.Enable = "on";

            app.UIAxes.Color = app.Mainapp.PlotAppearance.SpectrumWindow.Data.SpectrumBackgroundColor;
            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeFontSize;
            % app.TextArea.Value = "Ready";
            % pause(0.01);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: DataTypeDropDown
        function DataTypeDropDownValueChanged(app, event)
            if strcmp(app.DataTypeDropDown.Value,"Mean over all Channel")
                app.ChannelDropDown.Enable = "off";
            else
                app.ChannelDropDown.Enable = "on";
            end
            
            AllChannels = {};
            N = size(app.Mainapp.Data.Raw,1);  
            
            for i = 1:N
                AllChannels{i} = num2str(i);
            end

            SelectedChannel = str2double(app.ChannelDropDown.Value);
            app.ChannelDropDown.Items = AllChannels;

            
            [app.Mainapp.CurrentPlotData] = Analyse_Main_Window_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
        end

        % Value changed function: ChannelDropDown
        function ChannelDropDownValueChanged(app, event)
            AllChannels = {};
            N = size(app.Mainapp.Data.Raw,1);  
            
            for i = 1:N
                AllChannels{i} = num2str(i);
            end

            SelectedChannel = str2double(app.ChannelDropDown.Value);
            app.ChannelDropDown.Items = AllChannels;


            [app.Mainapp.CurrentPlotData] = Analyse_Main_Window_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
        end

        % Value changed function: DataSourceDropDown
        function DataSourceDropDownValueChanged(app, event)
            app.UIAxes_2.NextPlot = "replace";
            cla(app.UIAxes_2)
            app.UIAxes_2.NextPlot = "add";
            
            app.UIAxes.Color = app.Mainapp.PlotAppearance.SpectrumWindow.Data.SpectrumBackgroundColor;
            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeFontSize;

            AllChannels = {};
            N = size(app.Mainapp.Data.Raw,1);  
            
            for i = 1:N
                AllChannels{i} = num2str(i);
            end

            SelectedChannel = str2double(app.ChannelDropDown.Value);
            app.ChannelDropDown.Items = AllChannels;

            if strcmp(app.AnalysisDropDown.Value,"Band Power Individual Channel ")
                app.ChannelDropDown.Enable = "on";
                app.DataTypeDropDown.Enable = "on";

                [app.Mainapp.CurrentPlotData] = Analyse_Main_Window_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);

                [app.Mainapp.PowerSpecResults,app.BandPower,~] = Continous_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.Mainapp.PowerSpecResults,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'Just Frequency Bands',app.TwoORThreeD,app.Mainapp.CurrentPlotData);

                % app.TextArea.Value = "Ready";
                % pause(0.01);


            elseif strcmp(app.AnalysisDropDown.Value,"Band Power over Depth")
                % app.TextArea.Value = "Ready";
                % pause(0.01);

                app.ChannelDropDown.Enable = "off";
                app.DataTypeDropDown.Enable = "off";

                [app.Mainapp.PowerSpecResults,app.BandPower,app.Mainapp.CurrentPlotData] = Continous_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.Mainapp.PowerSpecResults,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'All',app.TwoORThreeD,app.Mainapp.CurrentPlotData);
                % app.TextArea.Value = "Ready";
                % pause(0.01);
            end
        end

        % Menu selected function: PNGMenu
        function PNGMenuSelected(app, event)
            [file, path] = uiputfile('*.png', 'Save as');

            exportapp(app.StaticSpectrumWindowUIFigure,fullfile(path,file)) 
        end

        % Menu selected function: JPGMenu
        function JPGMenuSelected(app, event)
            [file, path] = uiputfile('*.jpg', 'Save as');

            exportapp(app.StaticSpectrumWindowUIFigure,fullfile(path,file)) 
        end

        % Menu selected function: ChangeTitleMenu
        function ChangeTitleMenuSelected(app, event)
            Figure_Property_Change_Window(app.UIAxes,'title');
        end

        % Menu selected function: ChangeXLabelMenu
        function ChangeXLabelMenuSelected(app, event)
            Figure_Property_Change_Window(app.UIAxes,'xlabel');
        end

        % Menu selected function: ChangeYLabelMenu
        function ChangeYLabelMenuSelected(app, event)
            Figure_Property_Change_Window(app.UIAxes,'ylabel');
        end

        % Menu selected function: ChangeXlimMenu
        function ChangeXlimMenuSelected(app, event)
            Figure_Property_Change_Window(app.UIAxes,'xlim');
        end

        % Menu selected function: ChangeYlimMenu
        function ChangeYlimMenuSelected(app, event)
            Figure_Property_Change_Window(app.UIAxes,'ylim');
        end

        % Menu selected function: ChangeLegendMenu
        function ChangeLegendMenuSelected(app, event)
            Figure_Property_Change_Window(app.UIAxes,'legend');
        end

        % Menu selected function: ChangeFontsizeMenu
        function ChangeFontsizeMenuSelected(app, event)
            
        end

        % Value changed function: AnalysisDropDown
        function AnalysisDropDownValueChanged(app, event)
            % app.UIAxes.NextPlot = "replace";
            % plot(app.UIAxes,0,0);
            % app.UIAxes.NextPlot = "add";
            
            PowerDepth_handles = findobj(app.UIAxes, 'Tag', 'PowerDepth');
            delete(PowerDepth_handles(1:end));

            PWelch_handles = findobj(app.UIAxes, 'Tag', 'Pwelch');
            delete(PWelch_handles(1:end));

            app.UIAxes.Color = app.Mainapp.PlotAppearance.SpectrumWindow.Data.SpectrumBackgroundColor;
            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeFontSize;

            if strcmp(app.AnalysisDropDown.Value,"Band Power Individual Channel ")
                app.ChannelDropDown.Enable = "on";
                app.DataTypeDropDown.Enable = "on";
                app.FrequencyRangeHzEditField.Enable = "on";

                SelectedChannel = str2double(app.ChannelDropDown.Value);

                set(app.UIAxes, 'YDir', 'normal');

                [app.Mainapp.CurrentPlotData] = Analyse_Main_Window_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);

            elseif strcmp(app.AnalysisDropDown.Value,"Band Power over Depth")
                app.ChannelDropDown.Enable = "off";
                app.DataTypeDropDown.Enable = "off";
                app.FrequencyRangeHzEditField.Enable = "on";

                app.UIAxes.YScale = 'linear';

                [app.Mainapp.PowerSpecResults,app.BandPower,app.Mainapp.CurrentPlotData] = Continous_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.Mainapp.PowerSpecResults,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'All',app.TwoORThreeD,app.Mainapp.CurrentPlotData);
            end
        end

        % Value changed function: FrequencyRangeHzEditField
        function FrequencyRangeHzEditFieldValueChanged(app, event)
            [app.FrequencyRangeHzEditField.Value] = Utility_SimpleCheckInputs(app.FrequencyRangeHzEditField.Value,"Two",'1,100',0,0);

            if strcmp(app.AnalysisDropDown.Value,"Band Power Individual Channel ")
                app.ChannelDropDown.Enable = "on";
                app.DataTypeDropDown.Enable = "on";
                app.FrequencyRangeHzEditField.Enable = "on";

                SelectedChannel = str2double(app.ChannelDropDown.Value);

                set(app.UIAxes, 'YDir', 'normal');

                [app.Mainapp.CurrentPlotData] = Analyse_Main_Window_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);

            elseif strcmp(app.AnalysisDropDown.Value,"Band Power over Depth")
                app.ChannelDropDown.Enable = "off";
                app.DataTypeDropDown.Enable = "off";
                app.FrequencyRangeHzEditField.Enable = "on";

                [app.Mainapp.PowerSpecResults,app.BandPower,app.Mainapp.CurrentPlotData] = Continous_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.Mainapp.PowerSpecResults,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'All',app.TwoORThreeD,app.Mainapp.CurrentPlotData);
            end

        end

        % Menu selected function: Show2DimensionalPlotsMenu
        function Show2DimensionalPlotsMenuSelected(app, event)
            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";
            
            app.UIAxes.Color = app.Mainapp.PlotAppearance.SpectrumWindow.Data.SpectrumBackgroundColor;
            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeFontSize;

            app.TwoORThreeD = "TwoD";

            if strcmp(app.AnalysisDropDown.Value,"Band Power Individual Channel ")
                app.ChannelDropDown.Enable = "on";
                app.DataTypeDropDown.Enable = "on";

                SelectedChannel = str2double(app.ChannelDropDown.Value);

                set(app.UIAxes, 'YDir', 'normal');

                [app.Mainapp.CurrentPlotData] = Analyse_Main_Window_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);

            elseif strcmp(app.AnalysisDropDown.Value,"Band Power over Depth")
                app.ChannelDropDown.Enable = "off";
                app.DataTypeDropDown.Enable = "off";
                app.FrequencyRangeHzEditField.Enable = "on";

                [app.Mainapp.PowerSpecResults,app.BandPower,app.Mainapp.CurrentPlotData] = Continous_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.Mainapp.PowerSpecResults,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'All',app.TwoORThreeD,app.Mainapp.CurrentPlotData);
            end
        end

        % Menu selected function: matMenu
        function matMenuSelected(app, event)
            TimeRangePlot = str2double(app.Mainapp.TimeRangeViewBox.Value(1:end-1));
            if app.Mainapp.PreprocDataPlotCheckBox.Value == 1
                if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                else
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                end
            else
                StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
            end

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Static_Spectrum");
        end

        % Menu selected function: txtMenu
        function txtMenuSelected(app, event)
            TimeRangePlot = str2double(app.Mainapp.TimeRangeViewBox.Value(1:end-1));
            if app.Mainapp.PreprocDataPlotCheckBox.Value == 1
                if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                else
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                end
            else
                StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
            end

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Static_Spectrum");
        end

        % Menu selected function: csvMenu
        function csvMenuSelected(app, event)
            TimeRangePlot = str2double(app.Mainapp.TimeRangeViewBox.Value(1:end-1));
            if app.Mainapp.PreprocDataPlotCheckBox.Value == 1
                if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                else
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                end
            else
                StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
            end

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".csv",app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Static_Spectrum");
        end

        % Menu selected function: Show3DimensionalPlotsMenu
        function Show3DimensionalPlotsMenuSelected(app, event)
            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";
            
            app.UIAxes.Color = app.Mainapp.PlotAppearance.SpectrumWindow.Data.SpectrumBackgroundColor;
            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeFontSize;

            app.TwoORThreeD = "ThreeD";

            if strcmp(app.AnalysisDropDown.Value,"Band Power Individual Channel ")
                app.ChannelDropDown.Enable = "on";
                app.DataTypeDropDown.Enable = "on";

                SelectedChannel = str2double(app.ChannelDropDown.Value);

                set(app.UIAxes, 'YDir', 'normal');

                [app.Mainapp.CurrentPlotData] = Analyse_Main_Window_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);

            elseif strcmp(app.AnalysisDropDown.Value,"Band Power over Depth")
                app.ChannelDropDown.Enable = "off";
                app.DataTypeDropDown.Enable = "off";
                app.FrequencyRangeHzEditField.Enable = "on";

                [app.Mainapp.PowerSpecResults,app.BandPower,app.Mainapp.CurrentPlotData] = Continous_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.Mainapp.PowerSpecResults,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'All',app.TwoORThreeD,app.Mainapp.CurrentPlotData);
            end
        end

        % Menu selected function: FontSizeMenu
        function FontSizeMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'fontsize',app.Mainapp,"SpectrumFontSize");
            waitfor(h);
            
            if strcmp(app.AnalysisDropDown.Value,"Band Power Individual Channel ")
                
                SelectedChannel = str2double(app.ChannelDropDown.Value);
   
                [app.Mainapp.CurrentPlotData] = Analyse_Main_Window_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
            end

            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeFontSize;
        end

        % Menu selected function: LineWidthDataMenu
        function LineWidthDataMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'linewidth',app.Mainapp,"SpectrumLineWidth");
            waitfor(h);
            
            if strcmp(app.AnalysisDropDown.Value,"Band Power Individual Channel ")
                
                SelectedChannel = str2double(app.ChannelDropDown.Value);
   
                [app.Mainapp.CurrentPlotData] = Analyse_Main_Window_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
            end
          
        end

        % Menu selected function: ColorDataMenu
        function ColorDataMenuSelected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 

            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3

                app.Mainapp.PlotAppearance.SpectrumWindow.Data.SpectrumColor = selectedColor;

                if strcmp(app.AnalysisDropDown.Value,"Band Power Individual Channel ")
                    
                    SelectedChannel = str2double(app.ChannelDropDown.Value);
       
                    [app.Mainapp.CurrentPlotData] = Analyse_Main_Window_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
                end
            end
        end

        % Menu selected function: BackgorundColorMenu
        function BackgorundColorMenuSelected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 

            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.Mainapp.PlotAppearance.SpectrumWindow.Data.SpectrumBackgroundColor = selectedColor;
                app.UIAxes.Color = app.Mainapp.PlotAppearance.SpectrumWindow.Data.SpectrumBackgroundColor;
            end
        end

        % Menu selected function: XLabelMenu
        function XLabelMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'xlabel',app.Mainapp,"SpectrumWindowDataXlabel");
            waitfor(h);
            
            if strcmp(app.AnalysisDropDown.Value,"Band Power Individual Channel ")
                
                SelectedChannel = str2double(app.ChannelDropDown.Value);
   
                [app.Mainapp.CurrentPlotData] = Analyse_Main_Window_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
            end
        end

        % Menu selected function: YLabelMenu
        function YLabelMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'ylabel',app.Mainapp,"SpectrumWindowDataYlabel");
            waitfor(h);
            
            if strcmp(app.AnalysisDropDown.Value,"Band Power Individual Channel ")
                
                SelectedChannel = str2double(app.ChannelDropDown.Value);
   
                [app.Mainapp.CurrentPlotData] = Analyse_Main_Window_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
            end
        end

        % Menu selected function: RESETMenu
        function RESETMenuSelected(app, event)
        app.UIAxes.NextPlot = "replace"; 
        plot(app.UIAxes,0,0);
        app.UIAxes.NextPlot = "add"; 

        [app.Mainapp.PlotAppearance] = Organize_Set_Standard_PlotAppearance("SpectrumPlot",app.Mainapp.PlotAppearance);

        if strcmp(app.AnalysisDropDown.Value,"Band Power Individual Channel ")   
            SelectedChannel = str2double(app.ChannelDropDown.Value);
       
            [app.Mainapp.CurrentPlotData] = Analyse_Main_Window_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
    
        elseif strcmp(app.AnalysisDropDown.Value,"Band Power over Depth")
    
            [app.Mainapp.PowerSpecResults,app.BandPower,app.Mainapp.CurrentPlotData] = Continous_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.Mainapp.PowerSpecResults,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'All',app.TwoORThreeD,app.Mainapp.CurrentPlotData);
        end 
        end

        % Menu selected function: SaveCostumAppearanceMenu
        function SaveCostumAppearanceMenuSelected(app, event)
            app.Mainapp.PlotAppearance = Utility_Save_Load_Delete_Plot_Appearance(app.Mainapp.PlotAppearance,app.Mainapp.executableFolder,"Save");
        end

        % Menu selected function: DeleteCostumAppearanceMenu
        function DeleteCostumAppearanceMenuSelected(app, event)
            app.Mainapp.PlotAppearance = Utility_Save_Load_Delete_Plot_Appearance(app.Mainapp.PlotAppearance,app.Mainapp.executableFolder,"Delete");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StaticSpectrumWindowUIFigure = uifigure('Visible', 'off');
            app.StaticSpectrumWindowUIFigure.Name = 'Static Spectrum Window';
            app.StaticSpectrumWindowUIFigure.Icon = 'Logo.png';
            app.SnapshotMenu = uimenu(app.StaticSpectrumWindowUIFigure);
            app.SnapshotMenu.Text = '   Snapshot   ';
            app.SaveSnapshotofWindowMenu = uimenu(app.SnapshotMenu);
            app.SaveSnapshotofWindowMenu.Text = 'Save Snapshot of Window';
            app.PNGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.PNGMenu.MenuSelectedFcn = createCallbackFcn(app, @PNGMenuSelected, true);
            app.PNGMenu.Text = 'PNG';
            app.JPGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.JPGMenu.MenuSelectedFcn = createCallbackFcn(app, @JPGMenuSelected, true);
            app.JPGMenu.Text = 'JPG';
            app.PlotMenu_2 = uimenu(app.StaticSpectrumWindowUIFigure);
            app.PlotMenu_2.Text = '   Plot   ';
            app.Show2DimensionalPlotsMenu = uimenu(app.PlotMenu_2);
            app.Show2DimensionalPlotsMenu.MenuSelectedFcn = createCallbackFcn(app, @Show2DimensionalPlotsMenuSelected, true);
            app.Show2DimensionalPlotsMenu.Text = 'Show 2 Dimensional Plots';
            app.Show3DimensionalPlotsMenu = uimenu(app.PlotMenu_2);
            app.Show3DimensionalPlotsMenu.MenuSelectedFcn = createCallbackFcn(app, @Show3DimensionalPlotsMenuSelected, true);
            app.Show3DimensionalPlotsMenu.Text = 'Show 3 Dimensional Plots';
            app.ChangePlotPropertiesMenu = uimenu(app.PlotMenu_2);
            app.ChangePlotPropertiesMenu.Text = 'Change Plot Properties';
            app.DataPlotMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.DataPlotMenu.Text = 'Data Plot';
            app.FontSizeMenu = uimenu(app.DataPlotMenu);
            app.FontSizeMenu.MenuSelectedFcn = createCallbackFcn(app, @FontSizeMenuSelected, true);
            app.FontSizeMenu.Text = 'FontSize';
            app.LineWidthDataMenu = uimenu(app.DataPlotMenu);
            app.LineWidthDataMenu.MenuSelectedFcn = createCallbackFcn(app, @LineWidthDataMenuSelected, true);
            app.LineWidthDataMenu.Text = 'LineWidth Data';
            app.ColorDataMenu = uimenu(app.DataPlotMenu);
            app.ColorDataMenu.MenuSelectedFcn = createCallbackFcn(app, @ColorDataMenuSelected, true);
            app.ColorDataMenu.Text = 'Color Data';
            app.BackgorundColorMenu = uimenu(app.DataPlotMenu);
            app.BackgorundColorMenu.MenuSelectedFcn = createCallbackFcn(app, @BackgorundColorMenuSelected, true);
            app.BackgorundColorMenu.Text = 'Backgorund Color';
            app.XLabelMenu = uimenu(app.DataPlotMenu);
            app.XLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @XLabelMenuSelected, true);
            app.XLabelMenu.Text = 'XLabel';
            app.YLabelMenu = uimenu(app.DataPlotMenu);
            app.YLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @YLabelMenuSelected, true);
            app.YLabelMenu.Text = 'YLabel';
            app.RESETMenu = uimenu(app.DataPlotMenu);
            app.RESETMenu.MenuSelectedFcn = createCallbackFcn(app, @RESETMenuSelected, true);
            app.RESETMenu.Text = 'RESET';
            app.SaveCostumAppearanceMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.SaveCostumAppearanceMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveCostumAppearanceMenuSelected, true);
            app.SaveCostumAppearanceMenu.Text = 'Save Costum Appearance';
            app.DeleteCostumAppearanceMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.DeleteCostumAppearanceMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteCostumAppearanceMenuSelected, true);
            app.DeleteCostumAppearanceMenu.Text = 'Delete Costum Appearance';
            app.ExportDataMenu = uimenu(app.StaticSpectrumWindowUIFigure);
            app.ExportDataMenu.Text = '   Export Data   ';
            app.matMenu = uimenu(app.ExportDataMenu);
            app.matMenu.MenuSelectedFcn = createCallbackFcn(app, @matMenuSelected, true);
            app.matMenu.Text = '.mat';
            app.txtMenu = uimenu(app.ExportDataMenu);
            app.txtMenu.MenuSelectedFcn = createCallbackFcn(app, @txtMenuSelected, true);
            app.txtMenu.Text = '.txt';
            app.csvMenu = uimenu(app.ExportDataMenu);
            app.csvMenu.MenuSelectedFcn = createCallbackFcn(app, @csvMenuSelected, true);
            app.csvMenu.Text = '.csv';
            app.UIAxes = uiaxes(app.StaticSpectrumWindowUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.StaticSpectrumWindowUIFigure);
            xlabel(app.UIAxes_2, 'X')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.YTick = [];
            app.Panel = uipanel(app.StaticSpectrumWindowUIFigure);
            app.DataTypeDropDown_2Label = uilabel(app.Panel);
            app.DataTypeDropDown_2Label.Text = 'Data Type';
            app.DataSourceDropDown = uidropdown(app.Panel);
            app.DataSourceDropDown.Items = {'Raw Data', 'Preprocessed Data'};
            app.DataSourceDropDown.ValueChangedFcn = createCallbackFcn(app, @DataSourceDropDownValueChanged, true);
            app.DataSourceDropDown.Value = 'Raw Data';
            app.ChannelDropDownLabel = uilabel(app.Panel);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.Panel);
            app.ChannelDropDown.Items = {};
            app.ChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelDropDownValueChanged, true);
            app.ChannelDropDown.Value = {};
            app.AnalysisDropDownLabel = uilabel(app.Panel);
            app.AnalysisDropDownLabel.Text = 'Analysis';
            app.AnalysisDropDown = uidropdown(app.Panel);
            app.AnalysisDropDown.Items = {'Band Power Individual Channel ', 'Band Power over Depth'};
            app.AnalysisDropDown.ValueChangedFcn = createCallbackFcn(app, @AnalysisDropDownValueChanged, true);
            app.AnalysisDropDown.Value = 'Band Power Individual Channel ';
            app.AnalysisTypeDropDownLabel = uilabel(app.Panel);
            app.AnalysisTypeDropDownLabel.Text = 'Analysis Type';
            app.DataTypeDropDown = uidropdown(app.Panel);
            app.DataTypeDropDown.Items = {'Channel Individually', 'Mean over all Channel'};
            app.DataTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DataTypeDropDownValueChanged, true);
            app.DataTypeDropDown.Value = 'Channel Individually';
            app.FrequencyRangeHzEditFieldLabel = uilabel(app.Panel);
            app.FrequencyRangeHzEditFieldLabel.Text = {'Frequency'; 'Range (Hz)'};
            app.FrequencyRangeHzEditField = uieditfield(app.Panel, 'text');
            app.FrequencyRangeHzEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyRangeHzEditFieldValueChanged, true);
            app.FrequencyRangeHzEditField.Enable = 'off';
            app.FrequencyRangeHzEditField.Value = '1,100';
            app.StaticSpectrumWindowLabel = uilabel(app.StaticSpectrumWindowUIFigure);
            app.StaticSpectrumWindowLabel.Text = 'Static Spectrum Window';
            app.Image = uiimage(app.StaticSpectrumWindowUIFigure);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodToolboxLabel = uilabel(app.StaticSpectrumWindowUIFigure);
            app.NeuromodToolboxLabel.Text = 'Neuromod Toolbox';
            app.CloseButton = uibutton(app.StaticSpectrumWindowUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.ContextMenu3 = uicontextmenu(app.StaticSpectrumWindowUIFigure);
            app.ChangeTitleMenu = uimenu(app.ContextMenu3);
            app.ChangeTitleMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeTitleMenuSelected, true);
            app.ChangeTitleMenu.Text = 'Change Title';
            app.ChangeXLabelMenu = uimenu(app.ContextMenu3);
            app.ChangeXLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeXLabelMenuSelected, true);
            app.ChangeXLabelMenu.Text = 'Change X Label';
            app.ChangeYLabelMenu = uimenu(app.ContextMenu3);
            app.ChangeYLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeYLabelMenuSelected, true);
            app.ChangeYLabelMenu.Text = 'Change Y Label';
            app.ChangeXlimMenu = uimenu(app.ContextMenu3);
            app.ChangeXlimMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeXlimMenuSelected, true);
            app.ChangeXlimMenu.Text = 'Change Xlim';
            app.ChangeYlimMenu = uimenu(app.ContextMenu3);
            app.ChangeYlimMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeYlimMenuSelected, true);
            app.ChangeYlimMenu.Text = 'Change Ylim';
            app.ChangeLegendMenu = uimenu(app.ContextMenu3);
            app.ChangeLegendMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeLegendMenuSelected, true);
            app.ChangeLegendMenu.Text = 'Change Legend';
            app.ChangeFontsizeMenu = uimenu(app.ContextMenu3);
            app.ChangeFontsizeMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeFontsizeMenuSelected, true);
            app.ChangeFontsizeMenu.Text = 'Change Fontsize';
            % Assign app.ContextMenu3
            app.UIAxes.ContextMenu = app.ContextMenu3;
            app.UIAxes_2.ContextMenu = app.ContextMenu3;
            % Show the figure after all components are created
            app.StaticSpectrumWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Static_Power_Spectrum_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.StaticSpectrumWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.StaticSpectrumWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StaticSpectrumWindowUIFigure)
        end
    end
end