classdef Preprocessing_Events_Artefact_Rejection_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EventArtefactRejectionWindowUIFigure  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        RejectionSettingsPanel          matlab.ui.container.Panel
        Slider                          matlab.ui.control.Slider
        ChannelSpacingLabel             matlab.ui.control.Label
        MethodDropDown                  matlab.ui.control.DropDown
        MethodLabel                     matlab.ui.control.Label
        ChannelofInterestDropDown       matlab.ui.control.DropDown
        ChannelofInterestJustforERPPlotLabel  matlab.ui.control.Label
        ChannelSelectionEditField       matlab.ui.control.EditField
        ChannelSelectionLabel           matlab.ui.control.Label
        TrialsEditField                 matlab.ui.control.EditField
        TrialsEditFieldLabel            matlab.ui.control.Label
        TimeWindowtoectractsEditField   matlab.ui.control.EditField
        TimeWindowtoectractLabel        matlab.ui.control.Label
        CheckButton                     matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        CloseButton                     matlab.ui.control.Button
        ArtefactRejectionWindowLabel    matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        NeuromodLabel                   matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        ContextMenu                     matlab.ui.container.ContextMenu
        ChangeChannelSpacingLimitsMenu  matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        % Add a property to store the names of the vectors
        TextHandle = []; % Store the handle to the displayed text
        TrialsTextHandle = []; % Store the handle to the displayed text
        Mainapp = [];
        EventTime = [];
        colorMap = [];
        EventsRelatedApp = [];
    end

    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp, EventsRelatedApp)
            movegui(app.EventArtefactRejectionWindowUIFigure, 'center');
            
            app.Mainapp = Mainapp;
            app.EventsRelatedApp = EventsRelatedApp;

            %% Fill GUI fields

            app.Slider.Limits = app.EventsRelatedApp.Mainapp.Slider.Limits;
            app.Slider.Value = app.EventsRelatedApp.Mainapp.Slider.Value;
            
            texttoshow = {};
            for nchannel = 1:size(app.Mainapp.Data.EventRelatedData,1)
                texttoshow{nchannel} = num2str(nchannel);
            end

            app.ChannelofInterestDropDown.Items = texttoshow;

            app.ChannelSelectionEditField.Value = strcat("1,",num2str(size(app.Mainapp.Data.EventRelatedData,1)));

            if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                app.TrialsEditField.Value = strcat("1,",num2str(size(app.Mainapp.Data.PreprocessedEventRelatedData,2)));
            else
                app.TrialsEditField.Value = strcat("1,",num2str(size(app.Mainapp.Data.EventRelatedData,2)));
            end

            EventRelatedDataTimeRange = [];
                 
            spaceindicie = find(app.Mainapp.Data.Info.EventRelatedDataTimeRange == ' ');
            EventRelatedDataTimeRange(1) = str2double(app.Mainapp.Data.Info.EventRelatedDataTimeRange(1:spaceindicie));
            EventRelatedDataTimeRange(2) = str2double(app.Mainapp.Data.Info.EventRelatedDataTimeRange(spaceindicie+1:end));


            if strcmp(app.Mainapp.Data.Info.EventRelatedDataType,'Raw')
                app.EventTime = 0-EventRelatedDataTimeRange(1):1/app.Mainapp.Data.Info.NativeSamplingRate:EventRelatedDataTimeRange(2);
            else
                if isfield(app.Mainapp.Data.Info,"DownsampleFactor")
                    app.EventTime = 0-EventRelatedDataTimeRange(1):1/app.Mainapp.Data.Info.DownsampledSampleRate:EventRelatedDataTimeRange(2);
                else
                    app.EventTime = 0-EventRelatedDataTimeRange(1):1/app.Mainapp.Data.Info.NativeSamplingRate:EventRelatedDataTimeRange(2);
                end
            end

            app.UIAxes.FontSize = 10;
            app.UIAxes_2.FontSize = 10;
            app.UIAxes2.FontSize = 10;

            xlabel(app.UIAxes,'Time [s]');
            ylabel(app.UIAxes,'Signal [mV]');
            xlim(app.UIAxes,[app.EventTime(1) app.EventTime(end)]);

            xlabel(app.UIAxes_2,'Time [s]');
            ylabel(app.UIAxes_2,'Signal [mV]');
            xlim(app.UIAxes_2,[app.EventTime(1) app.EventTime(end)]);

            xlabel(app.UIAxes2, 'Time [s]');
            xlim(app.UIAxes2, [app.EventTime(1),app.EventTime(end)]);
            app.UIAxes2.YTick = [];
            app.UIAxes2.YLabel = [];

            %% Get GUi inputs
           
            if isempty(app.ChannelSelectionEditField.Value)
                msgbox("Error: No Channel Selected ");
                return;
            end

            %% Get Timewindow and trial information from GUI
            if isempty(app.TimeWindowtoectractsEditField.Value)
                TimeWindin(1,1) = app.EventTime(floor(length(app.EventTime)/3));
                TimeWindin(1,2) = app.EventTime(floor(length(app.EventTime)/3)*2);
            else
                indicesep = find(app.TimeWindowtoectractsEditField.Value == ',');
                TimeWindin(1,1) = str2double(app.TimeWindowtoectractsEditField.Value(1:indicesep(1)-1));
                TimeWindin(1,2) = str2double(app.TimeWindowtoectractsEditField.Value(indicesep+1:end));
            end

            %Channel
            indicesep = find(app.ChannelSelectionEditField.Value == ',');
            Channel(1,1) = str2double(app.ChannelSelectionEditField.Value(1:indicesep(1)-1));
            Channel(1,2) = str2double(app.ChannelSelectionEditField.Value(indicesep+1:end));
            if Channel(1,2) > size(app.Mainapp.Data.EventRelatedData,1)
                msgbox("Last channel bigger than channel number. Autosetting to all channel");
                Channel(1,2) = size(app.Mainapp.Data.EventRelatedData,1);
                app.ChannelSelectionEditField.Value = strcat("1,",num2str(size(app.Mainapp.Data.EventRelatedData,1)));
            end

            indicesep = find(app.TrialsEditField.Value == ',');
            Trials(1,1) = str2double(app.TrialsEditField.Value(1:indicesep(1)-1));
            Trials(1,2) = str2double(app.TrialsEditField.Value(indicesep+1:end));
       
            %ChanneltoPlot = str2double(app.ChannelofInterestDropDown.Value);
            
            %% Extract Data and Plot

            app.colorMap = app.Mainapp.tempcolorMapset;

            if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                [~,~,~] = Preprocessing_Events_Reject_and_Plot_Artefacts(app.Mainapp.Data.PreprocessedEventRelatedData,Channel,Trials,app.EventTime,TimeWindin,app.UIAxes,app.UIAxes_2,app.UIAxes2,app.ChannelofInterestDropDown.Value,app.Slider.Value,app.MethodDropDown.Value,'DeleteandPlot',app.colorMap,'All');
            else
                [~,~,~] = Preprocessing_Events_Reject_and_Plot_Artefacts(app.Mainapp.Data.EventRelatedData,Channel,Trials,app.EventTime,TimeWindin,app.UIAxes,app.UIAxes_2,app.UIAxes2,app.ChannelofInterestDropDown.Value,app.Slider.Value,app.MethodDropDown.Value,'DeleteandPlot',app.colorMap,'All');
            end

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            %% Get GUi inputs
           
            if isempty(app.ChannelSelectionEditField.Value)
                msgbox("Error: No Channel Selected ");
                return;
            end

            [app.ChannelSelectionEditField,app.TimeWindowtoectractsEditField,app.TrialsEditField,app.EventTime] = Preprocessing_Events_Check_Inputs_Artefact_Rejection(app.Mainapp.Data,app.ChannelSelectionEditField,app.TimeWindowtoectractsEditField,app.TrialsEditField,app.EventTime);

            %% Get Timewindow and trial information from GUI
            if isempty(app.TimeWindowtoectractsEditField.Value)
                TimeWindin(1,1) = app.EventTime(floor(length(app.EventTime)/3));
                TimeWindin(1,2) = app.EventTime(floor(length(app.EventTime)/3)*2);
            else
                indicesep = find(app.TimeWindowtoectractsEditField.Value == ',');
                TimeWindin(1,1) = str2double(app.TimeWindowtoectractsEditField.Value(1:indicesep(1)-1));
                TimeWindin(1,2) = str2double(app.TimeWindowtoectractsEditField.Value(indicesep+1:end));
            end

            %Channel
            indicesep = find(app.ChannelSelectionEditField.Value == ',');
            Channel(1,1) = str2double(app.ChannelSelectionEditField.Value(1:indicesep(1)-1));
            Channel(1,2) = str2double(app.ChannelSelectionEditField.Value(indicesep+1:end));
            if Channel(1,2) > size(app.Mainapp.Data.EventRelatedData,1)
                msgbox("Last channel bigger than channel number. Autosetting to all channel");
                Channel(1,2) = size(app.Mainapp.Data.EventRelatedData,1);
                app.ChannelSelectionEditField.Value = strcat("1,",num2str(size(app.Mainapp.Data.EventRelatedData,1)));
            end

            indicesep = find(app.TrialsEditField.Value == ',');
            Trials(1,1) = str2double(app.TrialsEditField.Value(1:indicesep(1)-1));
            Trials(1,2) = str2double(app.TrialsEditField.Value(indicesep+1:end));
       
            %ChanneltoPlot = str2double(app.ChannelofInterestDropDown.Value);
            
            %% Extract Data and Plot

            app.colorMap = app.Mainapp.tempcolorMapset;

            if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                [app.Mainapp.Data.PreprocessedEventRelatedData,~,~] = Preprocessing_Events_Reject_and_Plot_Artefacts(app.Mainapp.Data.PreprocessedEventRelatedData,Channel,Trials,app.EventTime,TimeWindin,app.UIAxes,app.UIAxes_2,app.UIAxes2,app.ChannelofInterestDropDown.Value,app.Slider.Value,app.MethodDropDown.Value,'Interpolating',app.colorMap,'NoInterpolationPlot');
            else
                [app.Mainapp.Data.PreprocessedEventRelatedData,~,~] = Preprocessing_Events_Reject_and_Plot_Artefacts(app.Mainapp.Data.EventRelatedData,Channel,Trials,app.EventTime,TimeWindin,app.UIAxes,app.UIAxes_2,app.UIAxes2,app.ChannelofInterestDropDown.Value,app.Slider.Value,app.MethodDropDown.Value,'Interpolating',app.colorMap,'NoInterpolationPlot');
            end

            [app.Mainapp.Data] = Preprocessing_Events_Add_Preprocessing_Info(app.Mainapp.Data,'Artefact Rejection',Channel,Trials,TimeWindin);

            Utility_Show_Info_Loaded_Data(app.Mainapp);

            Preprocessing_Events_PopulateInfoText(app.EventsRelatedApp.TextArea,app.Mainapp.Data);

            msgbox("Artefact rejection succesfully applied to dataset and saved.");
        end

        % Button pushed function: CheckButton
        function CheckButtonPushed(app, event)

            %% Get correct animal and session based on input
           
            if isempty(app.ChannelSelectionEditField.Value)
                msgbox("Error: No Channel Selected ");
                return;
            end

            [app.ChannelSelectionEditField,app.TimeWindowtoectractsEditField,app.TrialsEditField,app.EventTime] = Preprocessing_Events_Check_Inputs_Artefact_Rejection(app.Mainapp.Data,app.ChannelSelectionEditField,app.TimeWindowtoectractsEditField,app.TrialsEditField,app.EventTime);
                            
            %% Get Timewindow and trial information from GUI
            if isempty(app.TimeWindowtoectractsEditField.Value)
                TimeWindin(1,1) = app.EventTime(floor(length(app.EventTime)/3));
                TimeWindin(1,2) = app.EventTime(floor(length(app.EventTime)/3)*2);
            else
                indicesep = find(app.TimeWindowtoectractsEditField.Value == ',');
                TimeWindin(1,1) = str2double(app.TimeWindowtoectractsEditField.Value(1:indicesep(1)-1));
                TimeWindin(1,2) = str2double(app.TimeWindowtoectractsEditField.Value(indicesep+1:end));
            end

            %Channel
            indicesep = find(app.ChannelSelectionEditField.Value == ',');
            Channel(1,1) = str2double(app.ChannelSelectionEditField.Value(1:indicesep(1)-1));
            Channel(1,2) = str2double(app.ChannelSelectionEditField.Value(indicesep+1:end));
            if Channel(1,2) > size(app.Mainapp.Data.EventRelatedData,1)
                msgbox("Last channel bigger than channel number. Autosetting to all channel");
                Channel(1,2) = size(app.Mainapp.Data.EventRelatedData,1);
                app.ChannelSelectionEditField.Value = strcat("1,",num2str(size(app.Mainapp.Data.EventRelatedData,1)));
            end

            indicesep = find(app.TrialsEditField.Value == ',');
            Trials(1,1) = str2double(app.TrialsEditField.Value(1:indicesep(1)-1));
            Trials(1,2) = str2double(app.TrialsEditField.Value(indicesep+1:end));
       
            %ChanneltoPlot = str2double(app.ChannelofInterestDropDown.Value);
            
            %% Extract Data and Plot
            if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                [~,~,~] = Preprocessing_Events_Reject_and_Plot_Artefacts(app.Mainapp.Data.PreprocessedEventRelatedData,Channel,Trials,app.EventTime,TimeWindin,app.UIAxes,app.UIAxes_2,app.UIAxes2,app.ChannelofInterestDropDown.Value,app.Slider.Value,app.MethodDropDown.Value,'Interpolating',app.colorMap,'InterpolationPlot');
            else
                [~,~,~] = Preprocessing_Events_Reject_and_Plot_Artefacts(app.Mainapp.Data.EventRelatedData,Channel,Trials,app.EventTime,TimeWindin,app.UIAxes,app.UIAxes_2,app.UIAxes2,app.ChannelofInterestDropDown.Value,app.Slider.Value,app.MethodDropDown.Value,'Interpolating',app.colorMap,'InterpolationPlot');
            end

        end

        % Value changed function: TimeWindowtoectractsEditField
        function TimeWindowtoectractsEditFieldValueChanged(app, event)
            %% Get GUi inputs
           
            if isempty(app.ChannelSelectionEditField.Value)
                msgbox("Error: No Channel Selected ");
                return;
            end

            [app.ChannelSelectionEditField,app.TimeWindowtoectractsEditField,app.TrialsEditField,app.EventTime] = Preprocessing_Events_Check_Inputs_Artefact_Rejection(app.Mainapp.Data,app.ChannelSelectionEditField,app.TimeWindowtoectractsEditField,app.TrialsEditField,app.EventTime);

            %% Get Timewindow and trial information from GUI
            if isempty(app.TimeWindowtoectractsEditField.Value)
                TimeWindin(1,1) = app.EventTime(floor(length(app.EventTime)/3));
                TimeWindin(1,2) = app.EventTime(floor(length(app.EventTime)/3)*2);
            else
                indicesep = find(app.TimeWindowtoectractsEditField.Value == ',');
                TimeWindin(1,1) = str2double(app.TimeWindowtoectractsEditField.Value(1:indicesep(1)-1));
                TimeWindin(1,2) = str2double(app.TimeWindowtoectractsEditField.Value(indicesep+1:end));
            end

            %Channel
            indicesep = find(app.ChannelSelectionEditField.Value == ',');
            Channel(1,1) = str2double(app.ChannelSelectionEditField.Value(1:indicesep(1)-1));
            Channel(1,2) = str2double(app.ChannelSelectionEditField.Value(indicesep+1:end));
            if Channel(1,2) > size(app.Mainapp.Data.EventRelatedData,1)
                msgbox("Last channel bigger than channel number. Autosetting to all channel");
                Channel(1,2) = size(app.Mainapp.Data.EventRelatedData,1);
                app.ChannelSelectionEditField.Value = strcat("1,",num2str(size(app.Mainapp.Data.EventRelatedData,1)));
            end

            indicesep = find(app.TrialsEditField.Value == ',');
            Trials(1,1) = str2double(app.TrialsEditField.Value(1:indicesep(1)-1));
            Trials(1,2) = str2double(app.TrialsEditField.Value(indicesep+1:end));
       
            %ChanneltoPlot = str2double(app.ChannelofInterestDropDown.Value);
            
            %% Extract Data and Plot
            if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                [~,~,~] = Preprocessing_Events_Reject_and_Plot_Artefacts(app.Mainapp.Data.PreprocessedEventRelatedData,Channel,Trials,app.EventTime,TimeWindin,app.UIAxes,app.UIAxes_2,app.UIAxes2,app.ChannelofInterestDropDown.Value,app.Slider.Value,app.MethodDropDown.Value,'DeleteandPlot',app.colorMap,'ERP');
            else
                [~,~,~] = Preprocessing_Events_Reject_and_Plot_Artefacts(app.Mainapp.Data.EventRelatedData,Channel,Trials,app.EventTime,TimeWindin,app.UIAxes,app.UIAxes_2,app.UIAxes2,app.ChannelofInterestDropDown.Value,app.Slider.Value,app.MethodDropDown.Value,'DeleteandPlot',app.colorMap,'ERP');
            end

        end

        % Value changed function: TrialsEditField
        function TrialsEditFieldValueChanged(app, event)
            %% Get GUi inputs
           
            if isempty(app.ChannelSelectionEditField.Value)
                msgbox("Error: No Channel Selected ");
                return;
            end

            [app.ChannelSelectionEditField,app.TimeWindowtoectractsEditField,app.TrialsEditField,app.EventTime] = Preprocessing_Events_Check_Inputs_Artefact_Rejection(app.Mainapp.Data,app.ChannelSelectionEditField,app.TimeWindowtoectractsEditField,app.TrialsEditField,app.EventTime);

            %% Get Timewindow and trial information from GUI
            if isempty(app.TimeWindowtoectractsEditField.Value)
                TimeWindin(1,1) = app.EventTime(floor(length(app.EventTime)/3));
                TimeWindin(1,2) = app.EventTime(floor(length(app.EventTime)/3)*2);
            else
                indicesep = find(app.TimeWindowtoectractsEditField.Value == ',');
                TimeWindin(1,1) = str2double(app.TimeWindowtoectractsEditField.Value(1:indicesep(1)-1));
                TimeWindin(1,2) = str2double(app.TimeWindowtoectractsEditField.Value(indicesep+1:end));
            end

            %Channel
            indicesep = find(app.ChannelSelectionEditField.Value == ',');
            Channel(1,1) = str2double(app.ChannelSelectionEditField.Value(1:indicesep(1)-1));
            Channel(1,2) = str2double(app.ChannelSelectionEditField.Value(indicesep+1:end));
            if Channel(1,2) > size(app.Mainapp.Data.EventRelatedData,1)
                msgbox("Last channel bigger than channel number. Autosetting to all channel");
                Channel(1,2) = size(app.Mainapp.Data.EventRelatedData,1);
                app.ChannelSelectionEditField.Value = strcat("1,",num2str(size(app.Mainapp.Data.EventRelatedData,1)));
            end

            indicesep = find(app.TrialsEditField.Value == ',');
            Trials(1,1) = str2double(app.TrialsEditField.Value(1:indicesep(1)-1));
            Trials(1,2) = str2double(app.TrialsEditField.Value(indicesep+1:end));
                   
            %% Extract Data and Plot
            if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                [~,~,~] = Preprocessing_Events_Reject_and_Plot_Artefacts(app.Mainapp.Data.PreprocessedEventRelatedData,Channel,Trials,app.EventTime,TimeWindin,app.UIAxes,app.UIAxes_2,app.UIAxes2,app.ChannelofInterestDropDown.Value,app.Slider.Value,app.MethodDropDown.Value,'DeleteandPlot',app.colorMap,'All');
            else
                [~,~,~] = Preprocessing_Events_Reject_and_Plot_Artefacts(app.Mainapp.Data.EventRelatedData,Channel,Trials,app.EventTime,TimeWindin,app.UIAxes,app.UIAxes_2,app.UIAxes2,app.ChannelofInterestDropDown.Value,app.Slider.Value,app.MethodDropDown.Value,'DeleteandPlot',app.colorMap,'All');
            end
        end

        % Value changed function: ChannelSelectionEditField
        function ChannelSelectionEditFieldValueChanged(app, event)
          [app.ChannelSelectionEditField,app.TimeWindowtoectractsEditField,app.TrialsEditField,app.EventTime] = Preprocessing_Events_Check_Inputs_Artefact_Rejection(app.Mainapp.Data,app.ChannelSelectionEditField,app.TimeWindowtoectractsEditField,app.TrialsEditField,app.EventTime);
               
        
        end

        % Value changed function: ChannelofInterestDropDown
        function ChannelofInterestDropDownValueChanged(app, event)
            %% Check if ChanneltoPlot is within Channelrange selected
            
            %% Get GUi inputs
           
            if isempty(app.ChannelSelectionEditField.Value)
                msgbox("Error: No Channel Selected ");
                return;
            end

            [app.ChannelSelectionEditField,app.TimeWindowtoectractsEditField,app.TrialsEditField,app.EventTime] = Preprocessing_Events_Check_Inputs_Artefact_Rejection(app.Mainapp.Data,app.ChannelSelectionEditField,app.TimeWindowtoectractsEditField,app.TrialsEditField,app.EventTime);
 
            %% Get Timewindow and trial information from GUI
            if isempty(app.TimeWindowtoectractsEditField.Value)
                TimeWindin(1,1) = app.EventTime(floor(length(app.EventTime)/3));
                TimeWindin(1,2) = app.EventTime(floor(length(app.EventTime)/3)*2);
            else
                indicesep = find(app.TimeWindowtoectractsEditField.Value == ',');
                TimeWindin(1,1) = str2double(app.TimeWindowtoectractsEditField.Value(1:indicesep(1)-1));
                TimeWindin(1,2) = str2double(app.TimeWindowtoectractsEditField.Value(indicesep+1:end));
            end

            %Channel
            indicesep = find(app.ChannelSelectionEditField.Value == ',');
            Channel(1,1) = str2double(app.ChannelSelectionEditField.Value(1:indicesep(1)-1));
            Channel(1,2) = str2double(app.ChannelSelectionEditField.Value(indicesep+1:end));
            if Channel(1,2) > size(app.Mainapp.Data.EventRelatedData,1)
                msgbox("Last channel bigger than channel number. Autosetting to all channel");
                Channel(1,2) = size(app.Mainapp.Data.EventRelatedData,1);
                app.ChannelSelectionEditField.Value = strcat("1,",num2str(size(app.Mainapp.Data.EventRelatedData,1)));
            end

            indicesep = find(app.TrialsEditField.Value == ',');
            Trials(1,1) = str2double(app.TrialsEditField.Value(1:indicesep(1)-1));
            Trials(1,2) = str2double(app.TrialsEditField.Value(indicesep+1:end));
            
            %% Extract Data and Plot
            if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                [~,~,~] = Preprocessing_Events_Reject_and_Plot_Artefacts(app.Mainapp.Data.PreprocessedEventRelatedData,Channel,Trials,app.EventTime,TimeWindin,app.UIAxes,app.UIAxes_2,app.UIAxes2,app.ChannelofInterestDropDown.Value,app.Slider.Value,app.MethodDropDown.Value,'DeleteandPlot',app.colorMap,'ERP');
            else
                [~,~,~] = Preprocessing_Events_Reject_and_Plot_Artefacts(app.Mainapp.Data.EventRelatedData,Channel,Trials,app.EventTime,TimeWindin,app.UIAxes,app.UIAxes_2,app.UIAxes2,app.ChannelofInterestDropDown.Value,app.Slider.Value,app.MethodDropDown.Value,'DeleteandPlot',app.colorMap,'ERP');
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Callback function
        function SliderValueChanged(app, event)
   
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.EventArtefactRejectionWindowUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {569, 569};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {398, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: Slider
        function SliderValueChanged2(app, event)
            %% Get GUi inputs
           
            if isempty(app.ChannelSelectionEditField.Value)
                msgbox("Error: No Channel Selected ");
                return;
            end

            [app.ChannelSelectionEditField,app.TimeWindowtoectractsEditField,app.TrialsEditField,app.EventTime] = Preprocessing_Events_Check_Inputs_Artefact_Rejection(app.Mainapp.Data,app.ChannelSelectionEditField,app.TimeWindowtoectractsEditField,app.TrialsEditField,app.EventTime);
                            
            %% Get Timewindow and trial information from GUI
            if isempty(app.TimeWindowtoectractsEditField.Value)
                TimeWindin(1,1) = app.EventTime(floor(length(app.EventTime)/3));
                TimeWindin(1,2) = app.EventTime(floor(length(app.EventTime)/3)*2);
            else
                indicesep = find(app.TimeWindowtoectractsEditField.Value == ',');
                TimeWindin(1,1) = str2double(app.TimeWindowtoectractsEditField.Value(1:indicesep(1)-1));
                TimeWindin(1,2) = str2double(app.TimeWindowtoectractsEditField.Value(indicesep+1:end));
            end

            %Channel
            indicesep = find(app.ChannelSelectionEditField.Value == ',');
            Channel(1,1) = str2double(app.ChannelSelectionEditField.Value(1:indicesep(1)-1));
            Channel(1,2) = str2double(app.ChannelSelectionEditField.Value(indicesep+1:end));
            if Channel(1,2) > size(app.Mainapp.Data.EventRelatedData,1)
                msgbox("Last channel bigger than channel number. Autosetting to all channel");
                Channel(1,2) = size(app.Mainapp.Data.EventRelatedData,1);
                app.ChannelSelectionEditField.Value = strcat("1,",num2str(size(app.Mainapp.Data.EventRelatedData,1)));
            end

            indicesep = find(app.TrialsEditField.Value == ',');
            Trials(1,1) = str2double(app.TrialsEditField.Value(1:indicesep(1)-1));
            Trials(1,2) = str2double(app.TrialsEditField.Value(indicesep+1:end));
       
            %ChanneltoPlot = str2double(app.ChannelofInterestDropDown.Value);
            
            %% Extract Data and Plot
            if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                [~,~,~] = Preprocessing_Events_Reject_and_Plot_Artefacts(app.Mainapp.Data.PreprocessedEventRelatedData,Channel,Trials,app.EventTime,TimeWindin,app.UIAxes,app.UIAxes_2,app.UIAxes2,app.ChannelofInterestDropDown.Value,app.Slider.Value,app.MethodDropDown.Value,'DeleteandPlot',app.colorMap,'ERPAllChannel');
            else
                [~,~,~] = Preprocessing_Events_Reject_and_Plot_Artefacts(app.Mainapp.Data.EventRelatedData,Channel,Trials,app.EventTime,TimeWindin,app.UIAxes,app.UIAxes_2,app.UIAxes2,app.ChannelofInterestDropDown.Value,app.Slider.Value,app.MethodDropDown.Value,'DeleteandPlot',app.colorMap,'ERPAllChannel');
            end
            
        end

        % Menu selected function: ChangeChannelSpacingLimitsMenu
        function ChangeChannelSpacingLimitsMenuSelected(app, event)
            Channel_Spacing_Selection(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EventArtefactRejectionWindowUIFigure = uifigure('Visible', 'off');
            app.EventArtefactRejectionWindowUIFigure.Name = 'Event Artefact Rejection Window';
            app.EventArtefactRejectionWindowUIFigure.Icon = 'Logo.png';
            app.EventArtefactRejectionWindowUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.EventArtefactRejectionWindowUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.NeuromodLabel = uilabel(app.LeftPanel);
            app.NeuromodLabel.Text = 'Neuromod';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = 'Logo.png';
            app.ArtefactRejectionWindowLabel = uilabel(app.LeftPanel);
            app.ArtefactRejectionWindowLabel.Text = 'Artefact Rejection Window';
            app.CloseButton = uibutton(app.LeftPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.SaveButton = uibutton(app.LeftPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = {'Save as new'; 'Dataset'};
            app.CheckButton = uibutton(app.LeftPanel, 'push');
            app.CheckButton.ButtonPushedFcn = createCallbackFcn(app, @CheckButtonPushed, true);
            app.CheckButton.Text = {'Interpolated Artefact'; ' Time Window'};
            app.RejectionSettingsPanel = uipanel(app.LeftPanel);
            app.RejectionSettingsPanel.Title = 'Rejection Settings';
            app.TimeWindowtoectractLabel = uilabel(app.RejectionSettingsPanel);
            app.TimeWindowtoectractLabel.Text = {'Time Window of '; 'Artefact [from to]'};
            app.TimeWindowtoectractsEditField = uieditfield(app.RejectionSettingsPanel, 'text');
            app.TimeWindowtoectractsEditField.ValueChangedFcn = createCallbackFcn(app, @TimeWindowtoectractsEditFieldValueChanged, true);
            app.TimeWindowtoectractsEditField.Value = '-0.005,0.005';
            app.TrialsEditFieldLabel = uilabel(app.RejectionSettingsPanel);
            app.TrialsEditFieldLabel.Text = 'Trials';
            app.TrialsEditField = uieditfield(app.RejectionSettingsPanel, 'text');
            app.TrialsEditField.ValueChangedFcn = createCallbackFcn(app, @TrialsEditFieldValueChanged, true);
            app.ChannelSelectionLabel = uilabel(app.RejectionSettingsPanel);
            app.ChannelSelectionLabel.Text = {'Channel '; 'Selection'};
            app.ChannelSelectionEditField = uieditfield(app.RejectionSettingsPanel, 'text');
            app.ChannelSelectionEditField.ValueChangedFcn = createCallbackFcn(app, @ChannelSelectionEditFieldValueChanged, true);
            app.ChannelofInterestJustforERPPlotLabel = uilabel(app.RejectionSettingsPanel);
            app.ChannelofInterestJustforERPPlotLabel.Text = {'Channel to '; 'Plot'};
            app.ChannelofInterestDropDown = uidropdown(app.RejectionSettingsPanel);
            app.ChannelofInterestDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16'};
            app.ChannelofInterestDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelofInterestDropDownValueChanged, true);
            app.ChannelofInterestDropDown.Value = '1';
            app.MethodLabel = uilabel(app.RejectionSettingsPanel);
            app.MethodLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.RejectionSettingsPanel);
            app.MethodDropDown.Items = {'Linear Interpolation'};
            app.MethodDropDown.Value = 'Linear Interpolation';
            app.ChannelSpacingLabel = uilabel(app.RejectionSettingsPanel);
            app.ChannelSpacingLabel.Text = {'Channel '; 'Spacing'};
            app.Slider = uislider(app.RejectionSettingsPanel);
            app.Slider.Limits = [0.001 0.1];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged2, true);
            app.Slider.Value = 0.001;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.RightPanel);
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.ContextMenu = uicontextmenu(app.EventArtefactRejectionWindowUIFigure);
            app.ChangeChannelSpacingLimitsMenu = uimenu(app.ContextMenu);
            app.ChangeChannelSpacingLimitsMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeChannelSpacingLimitsMenuSelected, true);
            app.ChangeChannelSpacingLimitsMenu.Text = 'Change Channel Spacing Limits';
            % Assign app.ContextMenu
            app.Slider.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.EventArtefactRejectionWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Preprocessing_Events_Artefact_Rejection_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.EventArtefactRejectionWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.EventArtefactRejectionWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EventArtefactRejectionWindowUIFigure)
        end
    end
end