classdef Preprocessing_Events_Main_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PreprocessEventDataWindowUIFigure  matlab.ui.Figure
        DeleteIndividualTrialsMenu  matlab.ui.container.Menu
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        RightPanel                  matlab.ui.container.Panel
        InformationLabel            matlab.ui.control.Label
        ChannelRejectionButton      matlab.ui.control.Button
        TrialRejectionButton        matlab.ui.control.Button
        TextArea_2                  matlab.ui.control.TextArea
        ArtefactRejectionButton     matlab.ui.control.Button
        DeleteExistingPreprocessedEventDataButton  matlab.ui.control.Button
        TextArea                    matlab.ui.control.TextArea
        CloseButton                 matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Mainapp = []; % Description
        EventTime = [];
        TimearoundEvent = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            movegui(app.PreprocessEventDataWindowUIFigure, 'center');
            
            app.Mainapp = Mainapp;
            
            app = Utility_Change_Light_Dark_Mode(app,"Preprocess_Events_Main_Window");

            %% -------------------- Setup GUI Fields -------------------- 
            % for i = 1:length(app.Mainapp.Data.Info.EventChannelNames)
            %     app.EventChannelSelectionDropDown.Items{i} = convertStringsToChars(app.Mainapp.Data.Info.EventChannelNames{i});
            % end

            Preprocessing_Events_PopulateInfoText(app.TextArea,app.Mainapp.Data,app.Mainapp.Data.Info.EventChannelNames{1});
        end

        % Button pushed function: TrialRejectionButton
        function TrialRejectionButtonPushed(app, event)
            Preprocessing_Events_Trial_Rejection_Window(app.Mainapp,app);
        end

        % Button pushed function: ArtefactRejectionButton
        function ArtefactRejectionButtonPushed(app, event)
            Preprocessing_Events_Artefact_Rejection_Window(app.Mainapp,app);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.Mainapp.PreproEventsMainWindow = [];
            delete(app)
        end

        % Button pushed function: ChannelRejectionButton
        function ChannelRejectionButtonPushed(app, event)
            Preprocessing_Events_Channel_Rejection_and_Interpolation_Window(app.Mainapp,app);
        end

        % Button pushed function: DeleteExistingPreprocessedEventDataButton
        function DeleteExistingPreprocessedEventDataButtonPushed(app, event)
            if isfield(app.Mainapp.Data.Info,'EventRelatedPreprocessing')
                
                [app.Mainapp.Data,~] = Organize_Delete_Dataset_Components(app.Mainapp.Data,"PreprocessedEventRelatedData");

                Utility_Show_Info_Loaded_Data(app.Mainapp);

                Preprocessing_Events_PopulateInfoText(app.TextArea,app.Mainapp.Data,app.Mainapp.Data.Info.EventChannelNames{1});

                msgbox("Existing preprocessed event data was deleted.");

            else
                Preprocessing_Events_PopulateInfoText(app.TextArea,app.Mainapp.Data,app.Mainapp.Data.Info.EventChannelNames{1});

                msgbox("Event related data was not preprocessed yet.");
            end
        end

        % Close request function: PreprocessEventDataWindowUIFigure
        function PreprocessEventDataWindowUIFigureCloseRequest(app, event)
            app.Mainapp.PreproEventsMainWindow = [];
            delete(app)
        end

        % Menu selected function: DeleteIndividualTrialsMenu
        function DeleteIndividualTrialsMenuSelected(app, event)
            msgbox("Warning: Existing preprocessed event related data will be removed when deleting event trigger!");
            warning("Warning: Existing preprocessed event related data will be removed when deleting event trigger!");
            
            if isempty(app.Mainapp.ProbeViewWindowHandle) || ~isprop(app.Mainapp.ProbeViewWindowHandle,'ProbeViewUIFigure')
                app.Mainapp.ProbeViewWindowHandle = Probe_View_Window(app,'MainWindow');
            end
    
            if ~isempty(app.Mainapp.ProbeViewWindowHandle) && isprop(app.Mainapp.ProbeViewWindowHandle,'ProbeViewUIFigure') % Add option to probe view when available
                AlreadyIn = 0;
                for i = 1:length(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                    if strcmp(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Delete Event Trigger Window')
                        AlreadyIn = 1;
                    end
                end
                if AlreadyIn == 0
                    app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{end+1} = 'Delete Event Trigger Window';
                end 
            end

            if isfield(app.Mainapp.Data,'Events')
                app.Mainapp.EventIndiceRejectionWindow = Event_Indice_Rejection_Window(app.Mainapp);
                [app.Mainapp] = Utility_Set_ToolTips(app.Mainapp,app.Mainapp.ShowToolTipsSetting,"Deletetriggerindices");
            else
                msgbox("Error: No event data found yet.");
            end 
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.PreprocessEventDataWindowUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {313, 313};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PreprocessEventDataWindowUIFigure = uifigure('Visible', 'off');
            app.PreprocessEventDataWindowUIFigure.Name = 'Preprocess Event Data Window';
            app.PreprocessEventDataWindowUIFigure.Icon = 'Logo.png';
            app.PreprocessEventDataWindowUIFigure.CloseRequestFcn = createCallbackFcn(app, @PreprocessEventDataWindowUIFigureCloseRequest, true);
            app.PreprocessEventDataWindowUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.DeleteIndividualTrialsMenu = uimenu(app.PreprocessEventDataWindowUIFigure);
            app.DeleteIndividualTrialsMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteIndividualTrialsMenuSelected, true);
            app.DeleteIndividualTrialsMenu.Text = 'Delete Individual Trials';
            app.GridLayout = uigridlayout(app.PreprocessEventDataWindowUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.CloseButton = uibutton(app.RightPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.TextArea = uitextarea(app.RightPanel);
            app.DeleteExistingPreprocessedEventDataButton = uibutton(app.RightPanel, 'push');
            app.DeleteExistingPreprocessedEventDataButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteExistingPreprocessedEventDataButtonPushed, true);
            app.DeleteExistingPreprocessedEventDataButton.Text = {'Delete Existing'; 'Preprocessed Event Data'};
            app.ArtefactRejectionButton = uibutton(app.RightPanel, 'push');
            app.ArtefactRejectionButton.ButtonPushedFcn = createCallbackFcn(app, @ArtefactRejectionButtonPushed, true);
            app.ArtefactRejectionButton.Enable = 'off';
            app.ArtefactRejectionButton.Visible = 'off';
            app.ArtefactRejectionButton.Text = 'Artefact Rejection';
            app.TextArea_2 = uitextarea(app.RightPanel);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Enable = 'off';
            app.TextArea_2.Visible = 'off';
            app.TrialRejectionButton = uibutton(app.RightPanel, 'push');
            app.TrialRejectionButton.ButtonPushedFcn = createCallbackFcn(app, @TrialRejectionButtonPushed, true);
            app.TrialRejectionButton.Text = 'Trial Rejection';
            app.ChannelRejectionButton = uibutton(app.RightPanel, 'push');
            app.ChannelRejectionButton.ButtonPushedFcn = createCallbackFcn(app, @ChannelRejectionButtonPushed, true);
            app.ChannelRejectionButton.Text = {'Channel Rejection'; '(Interpolation)'};
            app.InformationLabel = uilabel(app.RightPanel);
            app.InformationLabel.Text = 'Information';
            % Show the figure after all components are created
            app.PreprocessEventDataWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Preprocessing_Events_Main_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.PreprocessEventDataWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.PreprocessEventDataWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PreprocessEventDataWindowUIFigure)
        end
    end
end