classdef AskForNeoParameter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NeoParameterUIFigure      matlab.ui.Figure
        ProceedButton             matlab.ui.control.Button
        InformationTextArea       matlab.ui.control.TextArea
        InformationTextAreaLabel  matlab.ui.control.Label
        KeepConsoleOpen_2         matlab.ui.control.CheckBox
        AutoDetectedRecordingSystemLabel  matlab.ui.control.Label
        RecordingSystemDropDown   matlab.ui.control.DropDown
    end

    
    properties (Access = public)
        Mainapp = []; % Description
        EventApp = [];
        NEOParameter = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp, EventApp)
            movegui(app.NeoParameterUIFigure, 'center'); 

            app.Mainapp = Mainapp;
            app.EventApp = EventApp;
            
            app = Utility_Change_Light_Dark_Mode(app,"Ask_For_NEO_Parameter");

            app.AutoDetectedRecordingSystemLabel.Text = 'Select Recording System';
            app.RecordingSystemDropDown.Items{1} = 'NEO Format Autodetection';
            app.RecordingSystemDropDown.Items{2} = 'New Open Ephys Format';
            app.RecordingSystemDropDown.Items{3} = 'Legacy Open Ephys Format';
            app.RecordingSystemDropDown.Items{4} = 'Neuralynx';
            app.RecordingSystemDropDown.Items{5} = 'Plexon';
            app.RecordingSystemDropDown.Items{6} = 'TdT (Tucker-Davis Technologies)';
            app.RecordingSystemDropDown.Items{7} = 'Blackrock';
            app.RecordingSystemDropDown.Items{8} = 'NeuroExplorer';

            
        end

        % Button pushed function: ProceedButton
        function ProceedButtonPushed(app, event)
            app.NEOParameter.Format = app.RecordingSystemDropDown.Value;
            app.NEOParameter.KeepOpen = app.KeepConsoleOpen_2.Value;
            
            uiresume(app.NeoParameterUIFigure);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NeoParameterUIFigure = uifigure('Visible', 'off');
            app.NeoParameterUIFigure.Name = 'Neo Parameter';
            app.NeoParameterUIFigure.Icon = 'Logo.png';
            app.RecordingSystemDropDown = uidropdown(app.NeoParameterUIFigure);
            app.RecordingSystemDropDown.Items = {};
            app.RecordingSystemDropDown.Value = {};
            app.AutoDetectedRecordingSystemLabel = uilabel(app.NeoParameterUIFigure);
            app.AutoDetectedRecordingSystemLabel.Text = {'Auto Detected'; 'Recording System'};
            app.KeepConsoleOpen_2 = uicheckbox(app.NeoParameterUIFigure);
            app.KeepConsoleOpen_2.Text = {'   Keep Python Console'; '   Open (Press enter in '; '   the console!)'};
            app.InformationTextAreaLabel = uilabel(app.NeoParameterUIFigure);
            app.InformationTextAreaLabel.Text = 'Information';
            app.InformationTextArea = uitextarea(app.NeoParameterUIFigure);
            app.InformationTextArea.Value = {'The automatically saved .mat file containing event data from data extraction with NEO could not be found. Therefore, NEO has to be executed again to load and save event data. '; 'After selecting parameter for NEO extraction, press ''Proceed'' to start the python script.'};
            app.ProceedButton = uibutton(app.NeoParameterUIFigure, 'push');
            app.ProceedButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedButtonPushed, true);
            app.ProceedButton.Text = 'Proceed';
            % Show the figure after all components are created
            app.NeoParameterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AskForNeoParameter(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NeoParameterUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NeoParameterUIFigure)
        end
    end
end