classdef Combine_Events < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CombineEventChannelUIFigure  matlab.ui.Figure
        NewCombinedEventNamescommaseparatedstringsEditField  matlab.ui.control.TextArea
        NewCombinedEventNameLabel    matlab.ui.control.Label
        CombineEventscommaseparatedIntegersEditField  matlab.ui.control.TextArea
        CombineEventscommaseparatedIntegersTextAreaLabel  matlab.ui.control.Label
        CloseButton                  matlab.ui.control.Button
        InformationTextArea          matlab.ui.control.TextArea
        InformationTextAreaLabel     matlab.ui.control.Label
        AddEventChannelCombinationButton  matlab.ui.control.Button
    end

    
    properties (Access = public)
        Mainapp = [];
        Eventapp = [];% Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp, Eventapp)
            % Moce this window in the middle of thescreen
            movegui(app.CombineEventChannelUIFigure, 'center');

            app.Mainapp = Mainapp;
            app.Eventapp = Eventapp;
            
            app = Utility_Change_Light_Dark_Mode(app,"Combine_Events_Window");

            if isempty(app.Eventapp.EventsToCombine)
                if ~strcmp(app.Mainapp.Data.Info.RecordingType,"Open Ephys")
                    StillAvailableEventChannel = str2double(strsplit(app.Eventapp.EventInfo.InputChannelNumber,','));
                else
                    StillAvailableEventChannel = str2double(strsplit(app.Eventapp.AdditionalEventInfo.InputChannelNumber,','));
                end
                if length(StillAvailableEventChannel) <=1
                    msgbox("Need two or more event channel to combine!")
                    try
                        app.Eventapp.CombineEventsWindow = [];
                    catch
        
                    end
        
                    delete(app)
                    return;
                end
                app.InformationTextArea.Value = ["Available event channel to combine: ";num2str(StillAvailableEventChannel);"";"Please enter the event channel numbers of each channel you want to combine to a single one (comma separated) with the field to the upper right. Channel are combined after clicking the 'Start Event Extraction' button was pressed. If you want to create two or more combined event channel, each consisting of different single event channels, open this window again and add a new combined event channel to the existing one(s)."];
            else

                if ~strcmp(app.Mainapp.Data.Info.RecordingType,"Open Ephys")
                    StillAvailableEventChannel = str2double(strsplit(app.Eventapp.EventInfo.InputChannelNumber,','));
                else
                    StillAvailableEventChannel = str2double(strsplit(app.Eventapp.AdditionalEventInfo.InputChannelNumber,','));
                end
                StillAvailableEventChannel(ismember(StillAvailableEventChannel,app.Eventapp.EventsToCombine.CombinedChannel)==1) = [];
            
                if isempty(StillAvailableEventChannel)
                    msgbox("No more event channel available to combine!")
                    try
                        app.Eventapp.CombineEventsWindow = [];
                    catch
        
                    end
        
                    delete(app)
                    return
                end

                if length(StillAvailableEventChannel) <=1
                    msgbox("Need two or more event channel to combine!")
                    try
                        app.Eventapp.CombineEventsWindow = [];
                    catch
        
                    end
        
                    delete(app)
                    return
                end

                if isempty(StillAvailableEventChannel)
                    StillAvailableEventChannel = "Non";
                else
                    StillAvailableEventChannel = num2str(StillAvailableEventChannel);
                end
                
                app.InformationTextArea.Value = ["Alrady added event channel to combine: ";num2str(app.Eventapp.EventsToCombine.CombinedChannel);"Still available event channel to combine: ";StillAvailableEventChannel;"";"Please enter the event channel numbers of each channel you want to combine to a single one (comma separated) with the field to the upper right. Channel are combined after clicking the 'Start Event Extraction' button was pressed. If you want to create two or more combined event channel, each consisting of different single event channels, open this window again and add a new combined event channel to the existing one(s)."];
            end

      
        end

        % Button pushed function: AddEventChannelCombinationButton
        function AddEventChannelCombinationButtonPushed(app, event)
            Markfirstime = 0;
            if ~isfield(app.Eventapp.EventsToCombine,'CombinedChannel')
                app.Eventapp.EventsToCombine.CombinedChannel = [];
                app.Eventapp.EventsToCombine.CombinedIdentity = [];
                app.Eventapp.EventsToCombine.NewCombinedChannelNames = [];
                Markfirstime = 1;
            end
            % selected Channel
            app.Eventapp.EventsToCombine.CombinedChannel = [app.Eventapp.EventsToCombine.CombinedChannel, str2double(strsplit(app.CombineEventscommaseparatedIntegersEditField.Value{1},','))];
            numberevents = length(str2double(strsplit(app.CombineEventscommaseparatedIntegersEditField.Value{1},',')));
            % New Event Identities
            if ~isempty(max(app.Eventapp.EventsToCombine.CombinedIdentity)+1)
                app.Eventapp.EventsToCombine.CombinedIdentity = [app.Eventapp.EventsToCombine.CombinedIdentity, zeros(1,numberevents)+max(app.Eventapp.EventsToCombine.CombinedIdentity)+1];
            else
                app.Eventapp.EventsToCombine.CombinedIdentity = [app.Eventapp.EventsToCombine.CombinedIdentity, zeros(1,numberevents)];
            end

            % Event Channel names
            if isempty(app.Eventapp.EventsToCombine.NewCombinedChannelNames)
                app.Eventapp.EventsToCombine.NewCombinedChannelNames = strsplit(app.NewCombinedEventNamescommaseparatedstringsEditField.Value{1},',');
            else
                NewChannelNames = strsplit(app.NewCombinedEventNamescommaseparatedstringsEditField.Value{1},',');
                for i = 1:length(NewChannelNames)
                    app.Eventapp.EventsToCombine.NewCombinedChannelNames{end+1} = NewChannelNames{i};
                end
            end

            if Markfirstime == 1
                texttoshow = {};
                texttoshow{1} = convertStringsToChars(strcat("Combining event channel selected with following channels being combined in one or more combined channel: ",num2str(app.Eventapp.EventsToCombine.CombinedChannel)));
                texttoshow1 = {};
                texttoshow1{1} = '';
                app.Eventapp.TextArea_2.Value = [texttoshow;texttoshow1;app.Eventapp.TextArea_2.Value];
            else
                a = app.Eventapp.TextArea_2.Value;
                a(1:2)=[];
                texttoshow = {};
                texttoshow{1} = convertStringsToChars(strcat("Combining event channel selected with following channels being combined in one or more combined channel: ",num2str(app.Eventapp.EventsToCombine.CombinedChannel)));
                texttoshow1 = {};
                texttoshow1{1} = '';
               
                app.Eventapp.TextArea_2.Value = '';
                app.Eventapp.TextArea_2.Value{1} = [texttoshow{1}];
                app.Eventapp.TextArea_2.Value{2} = [texttoshow1{1}];
                app.Eventapp.TextArea_2.Value = [app.Eventapp.TextArea_2.Value;a];
            end

            try
                app.Eventapp.CombineEventsWindow = [];
            catch

            end

            delete(app)
        end

        % Callback function
        function CombineEventscommaseparatedIntegersEditFieldValueChanged(app, event)


        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            try
                app.Eventapp.CombineEventsWindow = [];
            catch

            end

            delete(app)
        end

        % Close request function: CombineEventChannelUIFigure
        function CombineEventChannelUIFigureCloseRequest(app, event)
            
            try
                app.Eventapp.CombineEventsWindow = [];
            catch

            end

            delete(app)
        end

        % Value changed function: 
        % CombineEventscommaseparatedIntegersEditField
        function CombineEventscommaseparatedIntegersEditFieldValueChanged2(app, event)
            
            if ~isempty(app.CombineEventscommaseparatedIntegersEditField.Value{1})
                try
                    a = str2double(strsplit(app.CombineEventscommaseparatedIntegersEditField.Value{1},','));
                    
                    if isempty(app.Eventapp.EventsToCombine)
                        if ~strcmp(app.Mainapp.Data.Info.RecordingType,"Open Ephys")
                            StillAvailableEventChannel = str2double(strsplit(app.Eventapp.EventInfo.InputChannelNumber,','));
                        else
                            StillAvailableEventChannel = str2double(strsplit(app.Eventapp.AdditionalEventInfo.InputChannelNumber,','));
                        end
                    else
                        if ~strcmp(app.Mainapp.Data.Info.RecordingType,"Open Ephys")
                            StillAvailableEventChannel = str2double(strsplit(app.Eventapp.EventInfo.InputChannelNumber,','));
                        else
                            StillAvailableEventChannel = str2double(strsplit(app.Eventapp.AdditionalEventInfo.InputChannelNumber,','));
                        end
                        StillAvailableEventChannel(ismember(StillAvailableEventChannel,app.Eventapp.EventsToCombine.CombinedChannel)) = [];
                    end
                    
                    if sum(ismember(a,StillAvailableEventChannel)) < length(a)
                        msgbox("Error: One or more of the selected event channel are not part of the found event data! Refer to the information text are to see available channel numbers.");
                        app.CombineEventscommaseparatedIntegersEditField.Value{1} = event.PreviousValue;
                        return
                    end
                    
                catch
                    msgbox("Format Error. Please enter comma separated event channel numbers (integers)!");
                    app.CombineEventscommaseparatedIntegersEditField.Value{1} = '';
                    return
                end
            else

            end
            
            % Auto Populate event channel name field
            if isempty(app.NewCombinedEventNamescommaseparatedstringsEditField.Value{1})

                if ~isfield(app.Eventapp.EventsToCombine,'CombinedChannel')
                    app.NewCombinedEventNamescommaseparatedstringsEditField.Value{1} = 'Combined Event Channel 1';
                else
                    Numberalreadyaddedcombinedchannel = length(unique(app.Eventapp.EventsToCombine.CombinedIdentity));
                    app.NewCombinedEventNamescommaseparatedstringsEditField.Value{1} = convertStringsToChars(strcat("Combined Event Channel ",num2str(Numberalreadyaddedcombinedchannel+1)));
                end

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CombineEventChannelUIFigure = uifigure('Visible', 'off');
            app.CombineEventChannelUIFigure.Name = 'Combine Event Channel';
            app.CombineEventChannelUIFigure.Icon = 'Logo.png';
            app.CombineEventChannelUIFigure.CloseRequestFcn = createCallbackFcn(app, @CombineEventChannelUIFigureCloseRequest, true);
            app.AddEventChannelCombinationButton = uibutton(app.CombineEventChannelUIFigure, 'push');
            app.AddEventChannelCombinationButton.ButtonPushedFcn = createCallbackFcn(app, @AddEventChannelCombinationButtonPushed, true);
            app.AddEventChannelCombinationButton.Text = {'Add Event Channel'; 'Combination'};
            app.InformationTextAreaLabel = uilabel(app.CombineEventChannelUIFigure);
            app.InformationTextAreaLabel.Text = 'Information';
            app.InformationTextArea = uitextarea(app.CombineEventChannelUIFigure);
            app.InformationTextArea.Value = {'Please enter the event channel number of each channel you want to combine to a single one (comma separated) with the field to the right.'; ''; 'Channel are combined after clicking the ''Start Event Extraction'' button was pressed.'; ''; 'If you want to combine to a single channel multiple times (i.e. have two or more combined channel), open this window again.'};
            app.CloseButton = uibutton(app.CombineEventChannelUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.CombineEventscommaseparatedIntegersTextAreaLabel = uilabel(app.CombineEventChannelUIFigure);
            app.CombineEventscommaseparatedIntegersTextAreaLabel.Text = {'Combine Events'; '(comma separated Integers)'};
            app.CombineEventscommaseparatedIntegersEditField = uitextarea(app.CombineEventChannelUIFigure);
            app.CombineEventscommaseparatedIntegersEditField.ValueChangedFcn = createCallbackFcn(app, @CombineEventscommaseparatedIntegersEditFieldValueChanged2, true);
            app.NewCombinedEventNameLabel = uilabel(app.CombineEventChannelUIFigure);
            app.NewCombinedEventNameLabel.Text = {'New Combined Event Name'; ''};
            app.NewCombinedEventNamescommaseparatedstringsEditField = uitextarea(app.CombineEventChannelUIFigure);
            % Show the figure after all components are created
            app.CombineEventChannelUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Combine_Events(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.CombineEventChannelUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.CombineEventChannelUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CombineEventChannelUIFigure)
        end
    end
end