classdef Load_Costume_Trigger_Identity_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LoadCostumeTriggerIdentityUIFigure  matlab.ui.Figure
        ShowExampleFilesMenu      matlab.ui.container.Menu
        txtMenu                   matlab.ui.container.Menu
        OpenEditorMenu            matlab.ui.container.Menu
        ShowFileMenu              matlab.ui.container.Menu
        ProceedwithSelectedTriggerIdentitiesButton  matlab.ui.control.Button
        SelectFilecsvortxtButton  matlab.ui.control.Button
        InformationLabel          matlab.ui.control.Label
        TextArea                  matlab.ui.control.TextArea
        CloseButton               matlab.ui.control.Button
    end

    
    properties (Access = public)
        Mainapp = []% Description
        EventApp = []
        EventInfo = []
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp, EventApp)
            % Move this window in the middle of thescreen
            movegui(app.LoadCostumeTriggerIdentityUIFigure, 'center');
            
            app.Mainapp = Mainapp;
            app.EventApp = EventApp;

            app = Utility_Change_Light_Dark_Mode(app,"Load_Costume_Triggers");
            %% 

        end

        % Button pushed function: SelectFilecsvortxtButton
        function SelectFilecsvortxtButtonPushed(app, event)
            %Get user selcted a csv or txt
            % Prompt the user to select a folder
            % Open file explorer to select a .csv or .txt file
            [filename, pathname] = uigetfile({'*.csv;*.txt', 'CSV and Text Files (*.csv, *.txt)'}, ...
                                             'Select a CSV or TXT file');
            
            % Check if the user canceled the dialog
            if isequal(filename, 0)
                disp('Selection canceled.');
                return
            else
                NewPath = fullfile(pathname, filename);
            end

            % Read file lines
            lines = readlines(NewPath);
                        
            % Initialize cell array to hold event vectors
            Testdata = {};

            % Loop through each line
            deleteindexlater = [];
            NumValidLines = 0;
            for i = 1:numel(lines)
                line = strtrim(lines(i));  % Remove leading/trailing whitespace
                
                if isempty(lines) || NumValidLines == 1
                    deleteindexlater = [deleteindexlater,i];
                    continue;
                else
                    NumValidLines = NumValidLines +1;
                end

                if find(convertStringsToChars(line) == ':')
                    deleteindexlater = [deleteindexlater,i];
                    msgbox("No ':' allowed!");
                    continue;
                end
                % Split by commas
                parts = split(line, ',');
                                               
                % Extract numeric part (skip the first element which is the label)
                StringValues = parts(1:end);
                
                % Store in events cell
                Testdata{i} = StringValues;
                
            end

            if isempty(Testdata)
               
                app.EventInfo = [];

                if isfield(app.EventInfo,'UniqueIdentities')
                    fieldsToDelete = {'UniqueIdentities'};
                    % Delete fields
                    app.EventInfo = rmfield(app.EventInfo, fieldsToDelete);
                end
                if isfield(app.EventInfo,'AllIdentities')
                    fieldsToDelete = {'AllIdentities'};
                    % Delete fields
                    app.EventInfo = rmfield(app.EventInfo, fieldsToDelete);
                end
                app.TextArea.Value = "No valid file selected, probably format issue!";
                return;
            end

            AllTrigegrNames = unique(Testdata{1});

            app.EventInfo.UniqueIdentities = AllTrigegrNames;
            app.EventInfo.AllIdentities = Testdata{1};

            app.TextArea.Value = [strcat("Found ", num2str(length(app.EventInfo.UniqueIdentities))," unique identities: ");app.EventInfo.UniqueIdentities;"When you proceed, you can only load trigger from a single event channel!"];
            
        end

        % Button pushed function: 
        % ProceedwithSelectedTriggerIdentitiesButton
        function ProceedwithSelectedTriggerIdentitiesButtonPushed(app, event)
            %Resume execution to return the output
            uiresume(app.LoadCostumeTriggerIdentityUIFigure); 
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app)
        end

        % Close request function: LoadCostumeTriggerIdentityUIFigure
        function LoadCostumeTriggerIdentityUIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Menu selected function: OpenEditorMenu
        function OpenEditorMenuSelected(app, event)
            filetoopen = strcat(app.Mainapp.executableFolder,"/Modules/MISC/Variables (do not edit)/ExampleTriggerIdentities.txt");
            edit(filetoopen)
        end

        % Menu selected function: ShowFileMenu
        function ShowFileMenuSelected(app, event)
            filetoopen = strcat(app.Mainapp.executableFolder,'\Modules\MISC\Variables (do not edit)\ExampleTriggerIdentities.txt');
            
            % Use the system command 'explorer' with the '/select,' argument to highlight the file
            system(['explorer /select, "', filetoopen, '"']);

            msgbox("Opened the folder and highlighted the file in windows file explorer!")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LoadCostumeTriggerIdentityUIFigure = uifigure('Visible', 'off');
            app.LoadCostumeTriggerIdentityUIFigure.Name = 'Load Costume Trigger Identity';
            app.LoadCostumeTriggerIdentityUIFigure.Icon = 'Logo.png';
            app.LoadCostumeTriggerIdentityUIFigure.CloseRequestFcn = createCallbackFcn(app, @LoadCostumeTriggerIdentityUIFigureCloseRequest, true);
            app.ShowExampleFilesMenu = uimenu(app.LoadCostumeTriggerIdentityUIFigure);
            app.ShowExampleFilesMenu.Text = '   Show Example Files   ';
            app.txtMenu = uimenu(app.ShowExampleFilesMenu);
            app.txtMenu.Text = '.txt';
            app.OpenEditorMenu = uimenu(app.txtMenu);
            app.OpenEditorMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenEditorMenuSelected, true);
            app.OpenEditorMenu.Text = 'Open Editor';
            app.ShowFileMenu = uimenu(app.txtMenu);
            app.ShowFileMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowFileMenuSelected, true);
            app.ShowFileMenu.Text = 'Show File';
            app.CloseButton = uibutton(app.LoadCostumeTriggerIdentityUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.TextArea = uitextarea(app.LoadCostumeTriggerIdentityUIFigure);
            app.TextArea.Value = {'When trigger within an event channel are made up of multiple different types of events (i.e. 200 trigger, 50 of them for a sound being played with 10kHz, 50 for 15Khz and so on), you can load a .txt or .csv file containing this information. '; 'The file must contain a single column of data with as many comma separated values as TTL trigger in the event channel you want to load (can only be applied to a single event channel!). This assigns an ''identity'' to each trigger. With 200 trigger and 4 different identities spread over all trigger, the single event channel will be divided into 4 different event channel.'; 'For a reference see the ''''Show Example Files " tab on top of this window.'; ' '};
            app.InformationLabel = uilabel(app.LoadCostumeTriggerIdentityUIFigure);
            app.InformationLabel.Text = 'Information';
            app.SelectFilecsvortxtButton = uibutton(app.LoadCostumeTriggerIdentityUIFigure, 'push');
            app.SelectFilecsvortxtButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFilecsvortxtButtonPushed, true);
            app.SelectFilecsvortxtButton.Text = {'Select File '; '(.csv or .txt)'};
            app.ProceedwithSelectedTriggerIdentitiesButton = uibutton(app.LoadCostumeTriggerIdentityUIFigure, 'push');
            app.ProceedwithSelectedTriggerIdentitiesButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedwithSelectedTriggerIdentitiesButtonPushed, true);
            app.ProceedwithSelectedTriggerIdentitiesButton.Text = {'Proceed with Selected'; 'Trigger Identities'};
            % Show the figure after all components are created
            app.LoadCostumeTriggerIdentityUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Load_Costume_Trigger_Identity_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.LoadCostumeTriggerIdentityUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.LoadCostumeTriggerIdentityUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LoadCostumeTriggerIdentityUIFigure)
        end
    end
end