classdef Import_Trigger_Times_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImportTriggerTimesWindowUIFigure  matlab.ui.Figure
        DeleteEventTriggerIndicesMenu   matlab.ui.container.Menu
        ShowExampleFilesMenu            matlab.ui.container.Menu
        txtMenu                         matlab.ui.container.Menu
        OpenEditorMenu                  matlab.ui.container.Menu
        ShowFileMenu                    matlab.ui.container.Menu
        csvMenu                         matlab.ui.container.Menu
        OpenEditorMenu_2                matlab.ui.container.Menu
        ShowFileMenu_2                  matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        RightPanel                      matlab.ui.container.Panel
        PlotEventDataButton             matlab.ui.control.Button
        TakeasnewEventDataButton        matlab.ui.control.Button
        SelectFilecsvortxtButton        matlab.ui.control.Button
        EventTypeDropDown               matlab.ui.control.DropDown
        EventTypeDropDownLabel          matlab.ui.control.Label
        ImportSettingsLabel             matlab.ui.control.Label
        ImportSettingsPanel             matlab.ui.container.Panel
        Panel                           matlab.ui.container.Panel
        EventNamescommaseparatedLabel   matlab.ui.control.Label
        InputChannelSelectionEditField_2  matlab.ui.control.EditField
        NumberofEventChannelLabel       matlab.ui.control.Label
        InputChannelSelectionEditField  matlab.ui.control.EditField
        FilePathLabel                   matlab.ui.control.Label
        FileContentsLabel               matlab.ui.control.Label
        TextArea_2                      matlab.ui.control.TextArea
        TextArea                        matlab.ui.control.TextArea
        ExtractEventRelatedDataPanel    matlab.ui.container.Panel
        EventRelatedTrialsDataSettingsLabel  matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        TimeWindowAfterEventssEditField  matlab.ui.control.EditField
        TimeWindowAfterTriggersEditFieldLabel  matlab.ui.control.Label
        TimeWindowBeforeEventssEditField  matlab.ui.control.EditField
        TimeWindowBeforeTriggersEditFieldLabel  matlab.ui.control.Label
        CloseButton                     matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Mainapp = [] % Description
        Path = [];
        EventInfo = [];
        ShowChannelPlotWindow = [];
        TimearoundEvent = [];
        ShowChannelWindow = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            % Moce this window in the middle of thescreen
            movegui(app.ImportTriggerTimesWindowUIFigure, 'center');
            
            app.Mainapp = Mainapp;
            
            app = Utility_Change_Light_Dark_Mode(app,"ImportEvents");
            
            app.Path = [];
            

            %% Initiate Event Extraction Panel -- Basic Parameters
            app.TextArea.Value = "Please select a .csv or .txt file containing your trigger time points in samples. The fields on the left are automatically populated upon loading a file with the expected format. After selecting a file, you can change the event channel names displayed later in analysis windows.";

            app.TextArea_2.Value = ["Info: Save trigger time points in samples and in a single column. For multiple event channel, save multiple data columns in the file you import, one for each event.";"Each row MUST begin with a string saying 'Event Nr 1:','Event Nr 2:' and so on for each event/TTL channel. These MUST be followed by the event indices in samples, separated by a comma. See the 'Show Example Files' tab on top of this window for example files.";"In the 'Import Settings' panel you can specify the number of event channel in the selected file as well as the name for each event/TTL channel displayed in this GUI."];
            

        end

        % Callback function
        function FileTypeDropDownValueChanged(app, event)
      
        end

        % Button pushed function: SelectFilecsvortxtButton
        function SelectFilecsvortxtButtonPushed(app, event)
            if ~isempty(app.EventInfo)
                app.EventInfo = [];
            end

            [app.EventInfo,Errormessage,NewPath,outputLines] = Import_Events_Read_File(app.EventInfo);
            
            if ~isempty(Errormessage) || isempty(app.EventInfo)
                if ~isempty(Errormessage)
                    app.TextArea_2.Value = Errormessage;
                end
                app.TextArea.Value = 'Please select another file.';
                app.InputChannelSelectionEditField_2.Value = '';   
                app.InputChannelSelectionEditField.Value = ''; 
                app.Path = [];
            else
                app.InputChannelSelectionEditField_2.Value = '';
                for i = 1:app.EventInfo.NumberEvents
                    if i ~= 1
                        app.InputChannelSelectionEditField_2.Value = [app.InputChannelSelectionEditField_2.Value,',',strcat('Event TTL Nr. ', num2str(i))];
                    else
                        app.InputChannelSelectionEditField_2.Value = [app.InputChannelSelectionEditField_2.Value,strcat('Event TTL Nr. ', num2str(i))];
                    end
                end
                app.Path = NewPath;
                app.InputChannelSelectionEditField.Value = num2str(app.EventInfo.NumberEvents);
                app.TextArea_2.Value = outputLines;
                app.TextArea.Value = strcat("File ",app.Path ," succesfully loaded.");

            end

            
        end

        % Button pushed function: PlotEventDataButton
        function PlotEventDataButtonPushed(app, event)
            
            if isempty(app.EventInfo)
                msgbox("Error: No Event Channel found.");
                return;
            end
            
            app.ShowChannelWindow = Show_Imported_Event_Channel_Window(app.Mainapp,app);
            
        end

        % Button pushed function: TakeasnewEventDataButton
        function TakeasnewEventDataButtonPushed(app, event)
            if ~isfield(app.EventInfo,'TempEvents')
                app.TextArea_2.Value = "Error: No valid file containing events selected!";
                return
            end

            if isempty(app.EventInfo.TempEvents)
                app.TextArea_2.Value = "Error: No valid file containing events selected!";
                return
            end

            [app.Mainapp.Data,~] = Import_Events_Add_Imported_Events(app.Mainapp.Data,app.EventInfo);
            
            
            if ~isfield(app.Mainapp.Data,'Events')
                app.TextArea_2.Value = "No suitable event indices found. Check whether the correct file is loaded and sampling frequency is the same.";
            else
                succesmessagetoshow = [];
                for i = 1:length(app.Mainapp.Data.Events)
                    succesmessagetoshow{i} = ['Saved ',num2str(length(app.Mainapp.Data.Events{i})) ,' Event/TTL Trigger for channel ',app.Mainapp.Data.Info.EventChannelNames{i}];
                end
                app.TextArea_2.Value = succesmessagetoshow;

                %% Save event related infos for later on the fly extraction
                app.Mainapp.Data = Extract_Events_Module_Add_EventRelatedInfo(app.Mainapp.Data,app.TimeWindowBeforeEventssEditField.Value,app.TimeWindowAfterEventssEditField.Value);
            
            end
           
            %% Show new Info Header entries in main window

            Utility_Show_Info_Loaded_Data(app.Mainapp);
            
            [app.Mainapp] = Organize_Set_MainWindow_Dropdown(app.Mainapp,app.Mainapp.Data);
            
            if ~isempty(app.Mainapp.EventChannelDropDown.Items)
                app.Mainapp.EventChannelDropDown.Value = app.Mainapp.EventChannelDropDown.Items{1};
            end

            KeepSpikes = 0;
            KeepEvents = 0;
            if isfield(app.Mainapp.Data,'Spikes') && strcmp(app.Mainapp.Plotspikes,"Spikes")
                KeepSpikes = 1;
            end
            if isfield(app.Mainapp.Data,'Events') && strcmp(app.Mainapp.PlotEvents,"Events")
                KeepEvents = 1;
            end

            Organize_Reset_Main_Plot(app.Mainapp,0,KeepEvents,KeepSpikes,0,app.Mainapp.CurrentTimePoints);

            app.Mainapp = Utility_Change_Light_Dark_Mode(app.Mainapp,"MainWindow");
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            try
                if ~isempty(app.ShowChannelWindow)
                    delete(app.ShowChannelWindow)
                end
            end

            try
                app.Mainapp.ImportEventTTLWindow = [];
                delete(app)
            catch 
                delete(app)
            end
        end

        % Value changed function: TimeWindowBeforeEventssEditField
        function TimeWindowBeforeEventssEditFieldValueChanged(app, event)
            [app.TimeWindowBeforeEventssEditField.Value] = Utility_SimpleCheckInputs(app.TimeWindowBeforeEventssEditField.Value,"One",'0.2',0,0);
            value = app.TimeWindowBeforeEventssEditField.Value;
            app.TimearoundEvent(1) = str2double(value);
        end

        % Value changed function: TimeWindowAfterEventssEditField
        function TimeWindowAfterEventssEditFieldValueChanged(app, event)
            [app.TimeWindowAfterEventssEditField.Value] = Utility_SimpleCheckInputs(app.TimeWindowAfterEventssEditField.Value,"One",'0.5',0,0);
            value = app.TimeWindowAfterEventssEditField.Value;
            app.TimearoundEvent(2) = str2double(value);
        end

        % Callback function
        function AnalogThresholdVEditFieldValueChanged(app, event)
            [app.AnalogThresholdVEditField.Value] = Utility_SimpleCheckInputs(app.AnalogThresholdVEditField.Value,"One",'1',0,1);
        end

        % Value changed function: InputChannelSelectionEditField
        function InputChannelSelectionEditFieldValueChanged(app, event)
            commafind = find(app.InputChannelSelectionEditField.Value==',');

            if isempty(commafind)
                SelectedChannel = str2double(app.InputChannelSelectionEditField.Value);
                if isnan(SelectedChannel)
                    msgbox("Wrong format. Enter either single number or comma seperated numbers!")
                    app.InputChannelSelectionEditField.Value = event.PreviousValue;
                    return;
                end
            else
                strArray = strsplit(app.InputChannelSelectionEditField.Value, ',');
                numericVector = str2double(strArray);
                if sum(isnan(numericVector))>0
                    msgbox("Wrong format. Enter either single number or comma seperated numbers!")
                    app.InputChannelSelectionEditField.Value = event.PreviousValue;
                    return;
                else
                    SelectedChannel = numericVector;
                end
            end
            
            if ~strcmp(app.Mainapp.Data.Info.RecordingType,"Spike2")

                commafind = find(app.maxInputSelection==',');
                if isempty(commafind)
                    MaxSelectedChannel = str2double(app.maxInputSelection);
                else
                    strArray = strsplit(app.maxInputSelection, ',');
                    numericVector = str2double(strArray);
                    MaxSelectedChannel = numericVector;
                end

                if ~all(ismember(SelectedChannel,MaxSelectedChannel))
                    msgbox("Channel Selection exceeds max number of found channel! Value is autoadjusted to previous one.")
                    app.InputChannelSelectionEditField.Value = event.PreviousValue;
                    return;

                end

            end
            
        end

        % Close request function: ImportTriggerTimesWindowUIFigure
        function ImportTriggerTimesWindowUIFigureCloseRequest(app, event)
            if ~isempty(app.ShowChannelWindow)
                delete(app.ShowChannelWindow)
            end
            
            try
                app.Mainapp.ImportEventTTLWindow = [];
                delete(app)
            catch 
                delete(app)
            end
            
        end

        % Value changed function: InputChannelSelectionEditField_2
        function InputChannelSelectionEditField_2ValueChanged(app, event)
            
            app.EventInfo.EventNames = app.InputChannelSelectionEditField_2.Value;
        end

        % Menu selected function: txtMenu
        function txtMenuSelected(app, event)
            
        end

        % Menu selected function: OpenEditorMenu
        function OpenEditorMenuSelected(app, event)
            filetoopen = strcat(app.Mainapp.executableFolder,"/Modules/MISC/Variables (do not edit)/Example_Events.txt");
            edit(filetoopen)
        end

        % Menu selected function: OpenEditorMenu_2
        function OpenEditorMenu_2Selected(app, event)
            filetoopen = strcat(app.Mainapp.executableFolder,"/Modules/MISC/Variables (do not edit)/Example_Events.csv");
            edit(filetoopen)
        end

        % Menu selected function: ShowFileMenu
        function ShowFileMenuSelected(app, event)
            filetoopen = strcat(app.Mainapp.executableFolder,'\Modules\MISC\Variables (do not edit)\Example_Events.txt');
            
            % Use the system command 'explorer' with the '/select,' argument to highlight the file
            system(['explorer /select, "', filetoopen, '"']);

            msgbox("Opened the folder and highlighted the file in windows file explorer!")
        end

        % Menu selected function: ShowFileMenu_2
        function ShowFileMenu_2Selected(app, event)
            filetoopen = strcat(app.Mainapp.executableFolder,'\Modules\MISC\Variables (do not edit)\Example_Events.csv');
            
            % Use the system command 'explorer' with the '/select,' argument to highlight the file
            system(['explorer /select, "', filetoopen, '"']);

            msgbox("Opened the folder and highlighted the file in windows file explorer!")
        end

        % Menu selected function: DeleteEventTriggerIndicesMenu
        function DeleteEventTriggerIndicesMenuSelected(app, event)
            if isempty(app.Mainapp.ProbeViewWindowHandle) || ~isprop(app.Mainapp.ProbeViewWindowHandle,'ProbeViewUIFigure')
                app.Mainapp.ProbeViewWindowHandle = Probe_View_Window(app,'MainWindow');
            end
    
            if ~isempty(app.Mainapp.ProbeViewWindowHandle) && isprop(app.Mainapp.ProbeViewWindowHandle,'ProbeViewUIFigure') % Add option to probe view when available
                AlreadyIn = 0;
                for i = 1:length(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                    if strcmp(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Delete Event Trigger Window')
                        AlreadyIn = 1;
                    end
                end
                if AlreadyIn == 0
                    app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{end+1} = 'Delete Event Trigger Window';
                end 
            end

            if isfield(app.Mainapp.Data,'Events')
                app.Mainapp.EventIndiceRejectionWindow = Event_Indice_Rejection_Window(app.Mainapp);
                [app.Mainapp] = Utility_Set_ToolTips(app.Mainapp,app.Mainapp.ShowToolTipsSetting,"Deletetriggerindices");
            else
                msgbox("Error: No event data found yet.");
            end 
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ImportTriggerTimesWindowUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {569, 569};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImportTriggerTimesWindowUIFigure = uifigure('Visible', 'off');
            app.ImportTriggerTimesWindowUIFigure.Name = 'Import Trigger Times Window';
            app.ImportTriggerTimesWindowUIFigure.Icon = 'Logo.png';
            app.ImportTriggerTimesWindowUIFigure.CloseRequestFcn = createCallbackFcn(app, @ImportTriggerTimesWindowUIFigureCloseRequest, true);
            app.ImportTriggerTimesWindowUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.DeleteEventTriggerIndicesMenu = uimenu(app.ImportTriggerTimesWindowUIFigure);
            app.DeleteEventTriggerIndicesMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteEventTriggerIndicesMenuSelected, true);
            app.DeleteEventTriggerIndicesMenu.Text = '   Delete Event Trigger Indices   ';
            app.ShowExampleFilesMenu = uimenu(app.ImportTriggerTimesWindowUIFigure);
            app.ShowExampleFilesMenu.Text = '   Show Example Files   ';
            app.txtMenu = uimenu(app.ShowExampleFilesMenu);
            app.txtMenu.MenuSelectedFcn = createCallbackFcn(app, @txtMenuSelected, true);
            app.txtMenu.Text = '.txt';
            app.OpenEditorMenu = uimenu(app.txtMenu);
            app.OpenEditorMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenEditorMenuSelected, true);
            app.OpenEditorMenu.Text = 'Open Editor';
            app.ShowFileMenu = uimenu(app.txtMenu);
            app.ShowFileMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowFileMenuSelected, true);
            app.ShowFileMenu.Text = 'Show File';
            app.csvMenu = uimenu(app.ShowExampleFilesMenu);
            app.csvMenu.Text = '.csv';
            app.OpenEditorMenu_2 = uimenu(app.csvMenu);
            app.OpenEditorMenu_2.MenuSelectedFcn = createCallbackFcn(app, @OpenEditorMenu_2Selected, true);
            app.OpenEditorMenu_2.Text = 'Open Editor';
            app.ShowFileMenu_2 = uimenu(app.csvMenu);
            app.ShowFileMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ShowFileMenu_2Selected, true);
            app.ShowFileMenu_2.Text = 'Show File';
            app.GridLayout = uigridlayout(app.ImportTriggerTimesWindowUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.CloseButton = uibutton(app.RightPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.ExtractEventRelatedDataPanel = uipanel(app.RightPanel);
            app.ExtractEventRelatedDataPanel.Title = 'Extract Event Related Data';
            app.Panel_2 = uipanel(app.ExtractEventRelatedDataPanel);
            app.TimeWindowBeforeTriggersEditFieldLabel = uilabel(app.Panel_2);
            app.TimeWindowBeforeTriggersEditFieldLabel.Text = {'Time Window '; 'Before Trigger [s]'};
            app.TimeWindowBeforeEventssEditField = uieditfield(app.Panel_2, 'text');
            app.TimeWindowBeforeEventssEditField.ValueChangedFcn = createCallbackFcn(app, @TimeWindowBeforeEventssEditFieldValueChanged, true);
            app.TimeWindowBeforeEventssEditField.Value = '0.2';
            app.TimeWindowAfterTriggersEditFieldLabel = uilabel(app.Panel_2);
            app.TimeWindowAfterTriggersEditFieldLabel.Text = {'Time Window '; 'After Trigger [s]'};
            app.TimeWindowAfterEventssEditField = uieditfield(app.Panel_2, 'text');
            app.TimeWindowAfterEventssEditField.ValueChangedFcn = createCallbackFcn(app, @TimeWindowAfterEventssEditFieldValueChanged, true);
            app.TimeWindowAfterEventssEditField.Value = '0.7';
            app.EventRelatedTrialsDataSettingsLabel = uilabel(app.ExtractEventRelatedDataPanel);
            app.EventRelatedTrialsDataSettingsLabel.Text = 'Event Related (Trials) Data Settings';
            app.TextArea = uitextarea(app.RightPanel);
            app.TextArea_2 = uitextarea(app.RightPanel);
            app.FileContentsLabel = uilabel(app.RightPanel);
            app.FileContentsLabel.Text = 'File Contents';
            app.FilePathLabel = uilabel(app.RightPanel);
            app.FilePathLabel.Text = 'File Path';
            app.ImportSettingsPanel = uipanel(app.RightPanel);
            app.ImportSettingsPanel.Title = 'Import Settings';
            app.Panel = uipanel(app.ImportSettingsPanel);
            app.InputChannelSelectionEditField = uieditfield(app.Panel, 'text');
            app.InputChannelSelectionEditField.ValueChangedFcn = createCallbackFcn(app, @InputChannelSelectionEditFieldValueChanged, true);
            app.InputChannelSelectionEditField.Editable = 'off';
            app.NumberofEventChannelLabel = uilabel(app.Panel);
            app.NumberofEventChannelLabel.Text = {'Number of Event'; 'Channel'};
            app.InputChannelSelectionEditField_2 = uieditfield(app.Panel, 'text');
            app.InputChannelSelectionEditField_2.ValueChangedFcn = createCallbackFcn(app, @InputChannelSelectionEditField_2ValueChanged, true);
            app.EventNamescommaseparatedLabel = uilabel(app.Panel);
            app.EventNamescommaseparatedLabel.Text = 'Event Names (comma separated)';
            app.ImportSettingsLabel = uilabel(app.RightPanel);
            app.ImportSettingsLabel.Text = 'Import Settings';
            app.EventTypeDropDownLabel = uilabel(app.RightPanel);
            app.EventTypeDropDownLabel.Visible = 'off';
            app.EventTypeDropDownLabel.Text = 'Event Type';
            app.EventTypeDropDown = uidropdown(app.RightPanel);
            app.EventTypeDropDown.Items = {'Event Onset', 'Event Offset'};
            app.EventTypeDropDown.Enable = 'off';
            app.EventTypeDropDown.Visible = 'off';
            app.EventTypeDropDown.Value = 'Event Onset';
            app.SelectFilecsvortxtButton = uibutton(app.RightPanel, 'push');
            app.SelectFilecsvortxtButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFilecsvortxtButtonPushed, true);
            app.SelectFilecsvortxtButton.Text = {'Select File '; '(.csv or .txt)'};
            app.TakeasnewEventDataButton = uibutton(app.RightPanel, 'push');
            app.TakeasnewEventDataButton.ButtonPushedFcn = createCallbackFcn(app, @TakeasnewEventDataButtonPushed, true);
            app.TakeasnewEventDataButton.Text = {'Take as new'; 'Event Data'};
            app.PlotEventDataButton = uibutton(app.RightPanel, 'push');
            app.PlotEventDataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotEventDataButtonPushed, true);
            app.PlotEventDataButton.Text = {'Plot Event'; 'Data'};
            % Show the figure after all components are created
            app.ImportTriggerTimesWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Import_Trigger_Times_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ImportTriggerTimesWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ImportTriggerTimesWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImportTriggerTimesWindowUIFigure)
        end
    end
end