classdef Show_Imported_Event_Channel_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ShowImportedEventChannelUIFigure  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        RightPanel                     matlab.ui.container.Panel
        PlotOptionsLabel               matlab.ui.control.Label
        CloseButton                    matlab.ui.control.Button
        PlotOptionsPanel               matlab.ui.container.Panel
        DowsampledSampleRateHzEditField  matlab.ui.control.EditField
        DowsampledSampleRateHzEditFieldLabel  matlab.ui.control.Label
        SelectInputChannelNumberLabel  matlab.ui.control.Label
        FileTypeDropDown_2             matlab.ui.control.DropDown
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Mainapp = []; % Description
        EWapp = [];
        ChannelWithStuff = [];
        EventChannelNames = [];
        AdditionalEventInfo = [];
        startTimestamp = []; % OE
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp, EWapp)
            % Moce this window in the middle of thescreen
            movegui(app.ShowImportedEventChannelUIFigure, 'center');
            
            app.Mainapp = Mainapp;
            app.EWapp = EWapp;
             
            
            app = Utility_Change_Light_Dark_Mode(app,"PlotImportEvents");

            app.DowsampledSampleRateHzEditField.Value = "1000";
            
            chnames = strsplit(app.EWapp.EventInfo.EventNames,',');

            for i = 1:app.EWapp.EventInfo.NumberEvents
                app.FileTypeDropDown_2.Items{i} = chnames{i};
            end

            %% Handle Plotting
            %1. what was selected?
            chnames = strsplit(app.EWapp.EventInfo.EventNames,',');
            for i = 1:length(app.EWapp.EventInfo.NumberEvents)
                if strcmp(app.FileTypeDropDown_2.Value,chnames{i})
                    SelectedChannel = i;
                end
            end
            
            numdatapoints = round(app.Mainapp.Data.Info.num_data_points*(str2double(app.DowsampledSampleRateHzEditField.Value)/app.Mainapp.Data.Info.NativeSamplingRate));
           
            simulatedeventsignal = zeros(1,numdatapoints);
            
            
            %% downsample
            Eventtoplot = round(app.EWapp.EventInfo.TempEvents{SelectedChannel}*(str2double(app.DowsampledSampleRateHzEditField.Value)/app.Mainapp.Data.Info.NativeSamplingRate));
            
            fivemstail = round(0.005 / (1/str2double(app.DowsampledSampleRateHzEditField.Value)));
           
            for i = 1:length(Eventtoplot)
                simulatedeventsignal(Eventtoplot(i):Eventtoplot(i)+fivemstail) = 1;
            end
          
            Time = 0:1/str2double(app.DowsampledSampleRateHzEditField.Value):(length(simulatedeventsignal)-1)/str2double(app.DowsampledSampleRateHzEditField.Value);

            %% Plot Data
            Figure = app.UIAxes;

            EventPlotHandles = findobj(Figure, 'Type', 'line', 'Tag', 'Events');
            if length(EventPlotHandles)>1
                delete(EventPlotHandles(2:end));
            end
            
            EventPlotHandles = findobj(Figure, 'Type', 'line', 'Tag', 'Events');
            
            xlabel(Figure,"Time [s]")
            ylabel(Figure,"Signal [V]")

            title(Figure,strcat("Imported Event/TTL times from channel ", app.FileTypeDropDown_2.Value));
          
            xlim(Figure,[Time(1) Time(end)]);
            
            if isempty(EventPlotHandles)
                line(Figure,Time,simulatedeventsignal,'LineWidth',1.5,'Color','b', 'Tag', 'Events');
            else
                set(EventPlotHandles(1), 'XData', Time, 'YData', simulatedeventsignal, 'Color', 'b', 'Tag', 'Events');
            end

        end

        % Value changed function: FileTypeDropDown_2
        function FileTypeDropDown_2ValueChanged(app, event)
            %% Handle Plotting
            %1. what was selected?
            chnames = strsplit(app.EWapp.EventInfo.EventNames,',');
            for i = 1:app.EWapp.EventInfo.NumberEvents
                if strcmp(app.FileTypeDropDown_2.Value,chnames{i})
                    SelectedChannel = i;
                end
            end
            
            numdatapoints = round(app.Mainapp.Data.Info.num_data_points*(str2double(app.DowsampledSampleRateHzEditField.Value)/app.Mainapp.Data.Info.NativeSamplingRate));

            simulatedeventsignal = zeros(1,numdatapoints);
            
            Time = 0:1/str2double(app.DowsampledSampleRateHzEditField.Value):app.Mainapp.Data.Time(end);
            %% downsample
            Eventtoplot = round(app.EWapp.EventInfo.TempEvents{SelectedChannel}*(str2double(app.DowsampledSampleRateHzEditField.Value)/app.Mainapp.Data.Info.NativeSamplingRate));
            %% 5ms tails
            fivemstail = round(0.005 / (1/str2double(app.DowsampledSampleRateHzEditField.Value)));
           
            for i = 1:length(Eventtoplot)
                simulatedeventsignal(Eventtoplot(i):Eventtoplot(i)+fivemstail) = 1;
            end
          

            %% Plot Data
            Figure = app.UIAxes;

            EventPlotHandles = findobj(Figure, 'Type', 'line', 'Tag', 'Events');
            if length(EventPlotHandles)>1
                delete(EventPlotHandles(2:end));
            end
            
            EventPlotHandles = findobj(Figure, 'Type', 'line', 'Tag', 'Events');
            
            xlabel(Figure,"Time [s]")
            ylabel(Figure,"Signal [V]")

            title(Figure,strcat("Imported Event/TTL times from channel ", app.FileTypeDropDown_2.Value));
          
            xlim(Figure,[Time(1) Time(end)]);
            
            if isempty(EventPlotHandles)
                line(Figure,Time,simulatedeventsignal,'LineWidth',1.5,'Color','b', 'Tag', 'Events');
            else
                set(EventPlotHandles(1), 'XData', Time, 'YData', simulatedeventsignal, 'Color', 'b', 'Tag', 'Events');
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            try
                app.EWapp.ShowChannelWindow = [];
                delete(app);
            catch
                delete(app);
            end
        end

        % Value changed function: DowsampledSampleRateHzEditField
        function DowsampledSampleRateHzEditFieldValueChanged(app, event)
            [app.DowsampledSampleRateHzEditField.Value] = Utility_SimpleCheckInputs(app.DowsampledSampleRateHzEditField.Value,"One",'1000',0,0);

            downsamplerate = str2double(app.DowsampledSampleRateHzEditField.Value);
            if downsamplerate>app.EWapp.Mainapp.Data.Info.NativeSamplingRate
                msgbox("Error: Can not be higher than native samplerate!")
                return;
            end

            %% Downsample if not empty
            if ~isempty(app.DowsampledSampleRateHzEditField.Value)
                DownsampleRate = str2double(app.DowsampledSampleRateHzEditField.Value);
            
                if mod(app.Mainapp.Data.Info.NativeSamplingRate/DownsampleRate,1) ~=0
                    DownsampleFactor = round(app.Mainapp.Data.Info.NativeSamplingRate/DownsampleRate);
                    DownsampleRate = app.Mainapp.Data.Info.NativeSamplingRate/DownsampleFactor;
                    DownsampleFactor = app.Mainapp.Data.Info.NativeSamplingRate/DownsampleRate;
                    disp("Warning: Downsamplefactor resulting from entered sample rate is not an integer. Downsampled samplerate is adjusted accordingly!")

                    app.DowsampledSampleRateHzEditField.Value = num2str(DownsampleRate);

                else
                    DownsampleFactor = app.Mainapp.Data.Info.NativeSamplingRate/DownsampleRate;
                end


            end
            
            %% Handle Plotting
            %1. what was selected?
            chnames = strsplit(app.EWapp.EventInfo.EventNames,',');
            for i = 1:app.EWapp.EventInfo.NumberEvents
                if strcmp(app.FileTypeDropDown_2.Value,chnames{i})
                    SelectedChannel = i;
                end
            end
            
            numdatapoints = round(app.Mainapp.Data.Info.num_data_points*(str2double(app.DowsampledSampleRateHzEditField.Value)/app.Mainapp.Data.Info.NativeSamplingRate));

            simulatedeventsignal = zeros(1,numdatapoints);
            
            Time = 0:1/str2double(app.DowsampledSampleRateHzEditField.Value):app.Mainapp.Data.Time(end);
            %% downsample
            Eventtoplot = round(app.EWapp.EventInfo.TempEvents{SelectedChannel}*(str2double(app.DowsampledSampleRateHzEditField.Value)/app.Mainapp.Data.Info.NativeSamplingRate));
            
            fivemstail = round(0.005 / (1/str2double(app.DowsampledSampleRateHzEditField.Value)));
           
            for i = 1:length(Eventtoplot)
                simulatedeventsignal(Eventtoplot(i):Eventtoplot(i)+fivemstail) = 1;
            end
          

            %% Plot Data
            Figure = app.UIAxes;

            EventPlotHandles = findobj(Figure, 'Type', 'line', 'Tag', 'Events');
            if length(EventPlotHandles)>1
                delete(EventPlotHandles(2:end));
            end
            
            EventPlotHandles = findobj(Figure, 'Type', 'line', 'Tag', 'Events');
            
            xlabel(Figure,"Time [s]")
            ylabel(Figure,"Signal [V]")

            title(Figure,strcat("Imported Event/TTL times from channel ", app.FileTypeDropDown_2.Value));
          
            xlim(Figure,[Time(1) Time(end)]);
            
            if isempty(EventPlotHandles)
                line(Figure,Time,simulatedeventsignal,'LineWidth',1.5,'Color','b', 'Tag', 'Events');
            else
                set(EventPlotHandles(1), 'XData', Time, 'YData', simulatedeventsignal, 'Color', 'b', 'Tag', 'Events');
            end
            
        end

        % Close request function: ShowImportedEventChannelUIFigure
        function ShowImportedEventChannelUIFigureCloseRequest(app, event)
            try
                app.EWapp.ShowChannelWindow = [];
                delete(app);
            catch
                delete(app);
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ShowImportedEventChannelUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {502, 502};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ShowImportedEventChannelUIFigure = uifigure('Visible', 'off');
            app.ShowImportedEventChannelUIFigure.Name = 'Show Imported Event Channel';
            app.ShowImportedEventChannelUIFigure.Icon = 'Logo.png';
            app.ShowImportedEventChannelUIFigure.CloseRequestFcn = createCallbackFcn(app, @ShowImportedEventChannelUIFigureCloseRequest, true);
            app.ShowImportedEventChannelUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.ShowImportedEventChannelUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PlotOptionsPanel = uipanel(app.RightPanel);
            app.PlotOptionsPanel.Title = 'Plot Options';
            app.FileTypeDropDown_2 = uidropdown(app.PlotOptionsPanel);
            app.FileTypeDropDown_2.Items = {};
            app.FileTypeDropDown_2.ValueChangedFcn = createCallbackFcn(app, @FileTypeDropDown_2ValueChanged, true);
            app.FileTypeDropDown_2.Value = {};
            app.SelectInputChannelNumberLabel = uilabel(app.PlotOptionsPanel);
            app.SelectInputChannelNumberLabel.Text = {'Select Input Channel'; ' Number'};
            app.DowsampledSampleRateHzEditFieldLabel = uilabel(app.PlotOptionsPanel);
            app.DowsampledSampleRateHzEditFieldLabel.Text = {'Dowsampled'; 'Sample Rate [Hz]'};
            app.DowsampledSampleRateHzEditField = uieditfield(app.PlotOptionsPanel, 'text');
            app.DowsampledSampleRateHzEditField.ValueChangedFcn = createCallbackFcn(app, @DowsampledSampleRateHzEditFieldValueChanged, true);
            app.CloseButton = uibutton(app.RightPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.PlotOptionsLabel = uilabel(app.RightPanel);
            app.PlotOptionsLabel.Text = 'Plot Options';
            % Show the figure after all components are created
            app.ShowImportedEventChannelUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Show_Imported_Event_Channel_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ShowImportedEventChannelUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ShowImportedEventChannelUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ShowImportedEventChannelUIFigure)
        end
    end
end