classdef Analyse_Event_Related_Signal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Analyse_Event_Related_SignalUIFigure  matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        RightPanel                   matlab.ui.container.Panel
        Image                        matlab.ui.control.Image
        CloseButton                  matlab.ui.control.Button
        NeuromodLabel                matlab.ui.control.Label
        EventRelatedAnalysisWindowLabel  matlab.ui.control.Label
        SelectEventRelatedAnalysisPanel  matlab.ui.container.Panel
        EventRelatedPotentialButton  matlab.ui.control.Button
        StaticSpectrumButton         matlab.ui.control.Button
        TimeFrequencyPowerButton     matlab.ui.control.Button
        CurrentSourceDensityButton   matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Mainapp = []; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            % Moce this window in the middle of thescreen
            movegui(app.Analyse_Event_Related_SignalUIFigure, 'center');

            app.Mainapp = Mainapp;

            if ~isfield(app.Mainapp.Data,'EventRelatedData')
                msgbox("Error: No event related data found. Please first extract it using the 'Extract Events and Data' window");
                delete(app);
            end
        end

        % Button pushed function: EventRelatedPotentialButton
        function EventRelatedPotentialButtonPushed(app, event)
            if isempty(app.Mainapp.ProbeViewWindowHandle) || ~isprop(app.Mainapp.ProbeViewWindowHandle,'ProbeViewUIFigure')
                app.Mainapp.ProbeViewWindowHandle = Probe_View_Window(app.Mainapp,'MainWindow');
            end

            if ~isempty(app.Mainapp.ProbeViewWindowHandle) && isprop(app.Mainapp.ProbeViewWindowHandle,'ProbeViewUIFigure') % Add option to probe view when available
                AlreadyIn = 0;
                for i = 1:length(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                    if strcmp(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Event ERP Window')
                        AlreadyIn = 1;
                    end
                end
                if AlreadyIn == 0
                    app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{end+1} = 'Event ERP Window';
                end
            else % if no probe view available
 
            end

            app.Mainapp.EventLFPERP = ERP_Analysis_Window(app,app.Mainapp);
        end

        % Button pushed function: CurrentSourceDensityButton
        function CurrentSourceDensityButtonPushed(app, event)
            if isempty(app.Mainapp.ProbeViewWindowHandle) || ~isprop(app.Mainapp.ProbeViewWindowHandle,'ProbeViewUIFigure')
                app.Mainapp.ProbeViewWindowHandle = Probe_View_Window(app.Mainapp,'MainWindow');
            end

            if ~isempty(app.Mainapp.ProbeViewWindowHandle) && isprop(app.Mainapp.ProbeViewWindowHandle,'ProbeViewUIFigure') % Add option to probe view when available
                AlreadyIn = 0;
                for i = 1:length(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                    if strcmp(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Event CSD Window')
                        AlreadyIn = 1;
                    end
                end
                if AlreadyIn == 0
                    app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{end+1} = 'Event CSD Window';
                end
            else % if no probe view available
 
            end

            app.Mainapp.EventLFPCSD = CSD_Analysis_Window(app,app.Mainapp);
        end

        % Button pushed function: TimeFrequencyPowerButton
        function TimeFrequencyPowerButtonPushed(app, event)
            if isempty(app.Mainapp.ProbeViewWindowHandle) || ~isprop(app.Mainapp.ProbeViewWindowHandle,'ProbeViewUIFigure')
                app.Mainapp.ProbeViewWindowHandle = Probe_View_Window(app.Mainapp,'MainWindow');
            end

            if ~isempty(app.Mainapp.ProbeViewWindowHandle) && isprop(app.Mainapp.ProbeViewWindowHandle,'ProbeViewUIFigure') % Add option to probe view when available
                AlreadyIn = 0;
                for i = 1:length(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                    if strcmp(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Event Time Frequency Power Window')
                        AlreadyIn = 1;
                    end
                end
                if AlreadyIn == 0
                    app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{end+1} = 'Event Time Frequency Power Window';
                end
            else % if no probe view available
 
            end

            app.Mainapp.EventLFPTF = Time_Frequency_Power_Window(app,app.Mainapp);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.Mainapp.LFPEventsMainWindow = [];
            delete(app);
        end

        % Button pushed function: StaticSpectrumButton
        function StaticSpectrumButtonPushed(app, event)
            if isempty(app.Mainapp.ProbeViewWindowHandle) || ~isprop(app.Mainapp.ProbeViewWindowHandle,'ProbeViewUIFigure')
                app.Mainapp.ProbeViewWindowHandle = Probe_View_Window(app.Mainapp,'MainWindow');
            end

            if ~isempty(app.Mainapp.ProbeViewWindowHandle) && isprop(app.Mainapp.ProbeViewWindowHandle,'ProbeViewUIFigure') % Add option to probe view when available
                AlreadyIn = 0;
                for i = 1:length(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                    if strcmp(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Event Static Spectrum Window')
                        AlreadyIn = 1;
                    end
                end
                if AlreadyIn == 0
                    app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{end+1} = 'Event Static Spectrum Window';
                end
            else % if no probe view available
 
            end

            app.Mainapp.EventLFPSSP = Event_Static_Power_Spectrum_Window(app.Mainapp);
        end

        % Close request function: Analyse_Event_Related_SignalUIFigure
        function Analyse_Event_Related_SignalUIFigureCloseRequest(app, event)
            app.Mainapp.LFPEventsMainWindow = [];
            delete(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.Analyse_Event_Related_SignalUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {213, 213};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Analyse_Event_Related_SignalUIFigure = uifigure('Visible', 'off');
            app.Analyse_Event_Related_SignalUIFigure.Name = 'Analyse_Event_Related_Signal';
            app.Analyse_Event_Related_SignalUIFigure.Icon = 'Logo.png';
            app.Analyse_Event_Related_SignalUIFigure.CloseRequestFcn = createCallbackFcn(app, @Analyse_Event_Related_SignalUIFigureCloseRequest, true);
            app.Analyse_Event_Related_SignalUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.Analyse_Event_Related_SignalUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.SelectEventRelatedAnalysisPanel = uipanel(app.RightPanel);
            app.SelectEventRelatedAnalysisPanel.Title = 'Select Event Related Analysis';
            app.CurrentSourceDensityButton = uibutton(app.SelectEventRelatedAnalysisPanel, 'push');
            app.CurrentSourceDensityButton.ButtonPushedFcn = createCallbackFcn(app, @CurrentSourceDensityButtonPushed, true);
            app.CurrentSourceDensityButton.Text = {'Current Source'; 'Density'};
            app.TimeFrequencyPowerButton = uibutton(app.SelectEventRelatedAnalysisPanel, 'push');
            app.TimeFrequencyPowerButton.ButtonPushedFcn = createCallbackFcn(app, @TimeFrequencyPowerButtonPushed, true);
            app.TimeFrequencyPowerButton.Text = {'Time-Frequency'; 'Power'};
            app.StaticSpectrumButton = uibutton(app.SelectEventRelatedAnalysisPanel, 'push');
            app.StaticSpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @StaticSpectrumButtonPushed, true);
            app.StaticSpectrumButton.Text = 'Static Spectrum';
            app.EventRelatedPotentialButton = uibutton(app.SelectEventRelatedAnalysisPanel, 'push');
            app.EventRelatedPotentialButton.ButtonPushedFcn = createCallbackFcn(app, @EventRelatedPotentialButtonPushed, true);
            app.EventRelatedPotentialButton.Text = {'Event Related'; 'Potential'};
            app.EventRelatedAnalysisWindowLabel = uilabel(app.RightPanel);
            app.EventRelatedAnalysisWindowLabel.Text = 'Event Related Analysis Window';
            app.NeuromodLabel = uilabel(app.RightPanel);
            app.NeuromodLabel.Text = 'Neuromod';
            app.CloseButton = uibutton(app.RightPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = 'Logo.png';
            % Show the figure after all components are created
            app.Analyse_Event_Related_SignalUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Analyse_Event_Related_Signal(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.Analyse_Event_Related_SignalUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.Analyse_Event_Related_SignalUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Analyse_Event_Related_SignalUIFigure)
        end
    end
end