classdef ERP_Analysis_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EventRelatedAnalysisUIFigure   matlab.ui.Figure
        SnapshotMenu                   matlab.ui.container.Menu
        SaveSnapshotofWindowMenu       matlab.ui.container.Menu
        PNGMenu                        matlab.ui.container.Menu
        JPGMenu                        matlab.ui.container.Menu
        PlotMenu                       matlab.ui.container.Menu
        ChangePlotPropertiesMenu       matlab.ui.container.Menu
        SingleERPPlotMenu              matlab.ui.container.Menu
        FontSizeMenu                   matlab.ui.container.Menu
        LineWidthEventsMenu_3          matlab.ui.container.Menu
        LineWidthMeanMenu              matlab.ui.container.Menu
        LineWidthTriggerMenu           matlab.ui.container.Menu
        ColorEventsMenu_3              matlab.ui.container.Menu
        ColorMeanMenu                  matlab.ui.container.Menu
        ColorTriggerMenu               matlab.ui.container.Menu
        BackgorundColorMenu            matlab.ui.container.Menu
        XLabelMenu                     matlab.ui.container.Menu
        YLabelMenu                     matlab.ui.container.Menu
        RESETMenu                      matlab.ui.container.Menu
        MultipleERPPlotMenu_2          matlab.ui.container.Menu
        FontSizeMenu_3                 matlab.ui.container.Menu
        LineWidthMeanMenu_2            matlab.ui.container.Menu
        LineWidthTriggerMenu_2         matlab.ui.container.Menu
        ColorTriggerMenu_2             matlab.ui.container.Menu
        BackgorundColorMenu_2          matlab.ui.container.Menu
        XLabelMenu_3                   matlab.ui.container.Menu
        YLabelMenu_3                   matlab.ui.container.Menu
        RESETMenu_3                    matlab.ui.container.Menu
        SaveCostumeAppearanceMenu      matlab.ui.container.Menu
        DeleteCostumeAppearanceMenu    matlab.ui.container.Menu
        ExportDataMenu                 matlab.ui.container.Menu
        ERPoverEventsMenu              matlab.ui.container.Menu
        matMenu                        matlab.ui.container.Menu
        txtMenu                        matlab.ui.container.Menu
        csvMenu                        matlab.ui.container.Menu
        ERPoverChannelMenu             matlab.ui.container.Menu
        matMenu_2                      matlab.ui.container.Menu
        txtMenu_2                      matlab.ui.container.Menu
        csvMenu_2                      matlab.ui.container.Menu
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        RightPanel                     matlab.ui.container.Panel
        NeuromodLabel                  matlab.ui.control.Label
        EventRelatedPotentialWindowLabel  matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        Slider                         matlab.ui.control.Slider
        DataLineSpacingLabel           matlab.ui.control.Label
        CloseButton                    matlab.ui.control.Button
        ERPAnalysisSettingsPanel       matlab.ui.container.Panel
        ChannelSelectionDropDown_2     matlab.ui.control.DropDown
        ChannelSelectionDropDown_2Label  matlab.ui.control.Label
        EventNumberSelectionEditField  matlab.ui.control.EditField
        EventNumberSelectionEditFieldLabel  matlab.ui.control.Label
        DataTypeDropDown               matlab.ui.control.DropDown
        DataTypeDropDownLabel          matlab.ui.control.Label
        ChannelSelectionDropDown       matlab.ui.control.EditField
        ChannelLabel                   matlab.ui.control.Label
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        ContextMenu4                   matlab.ui.container.ContextMenu
        ChangeSpacingLimitsMenu        matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        EventRelatedWindow = []; % Description
        EventTime = [];
        TimearoundEvent = [];
        colorMap = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, EventRelatedWindow)
            movegui(app.EventRelatedAnalysisUIFigure, 'center');

            app.EventRelatedWindow = EventRelatedWindow;

            app.UIAxes.Color = app.EventRelatedWindow.Mainapp.PlotAppearance.ERPWindow.SingleERP.BackgroundColor;
            app.UIAxes.FontSize = app.EventRelatedWindow.Mainapp.PlotAppearance.ERPWindow.SingleERP.FontSize;

            app.UIAxes_2.Color = app.EventRelatedWindow.Mainapp.PlotAppearance.ERPWindow.MultipleERP.BackgroundColor;
            app.UIAxes_2.FontSize = app.EventRelatedWindow.Mainapp.PlotAppearance.ERPWindow.MultipleERP.FontSize;

            if ~isfield(app.EventRelatedWindow.Mainapp.Data,'Events')
                msgbox("No events extracted. Please extract events first!. Exiting.");
                delete(app);
                return;
            end
            
            app.Slider.Limits = app.EventRelatedWindow.Mainapp.Slider.Limits;
            app.Slider.Value = app.EventRelatedWindow.Mainapp.Slider.Value;
            
            app.colorMap = app.EventRelatedWindow.Mainapp.tempcolorMapset;

            %% Fill Event Channel Selection, Event umber selection and channel selection fields
            
            for nchannel = 1:length(app.EventRelatedWindow.Mainapp.Data.Info.ProbeInfo.ActiveChannel)
                app.ChannelSelectionDropDown_2.Items{nchannel} = num2str(app.EventRelatedWindow.Mainapp.Data.Info.ProbeInfo.ActiveChannel(nchannel));
            end

            if isfield(app.EventRelatedWindow.Mainapp.Data,'PreprocessedEventRelatedData')
                app.DataTypeDropDown.Items{1} = 'Raw Event Related Data';
                app.DataTypeDropDown.Items{2} = 'Preprocessed Event Related Data';
            else
                app.DataTypeDropDown.Items{1} = 'Raw Event Related Data';
            end
            
            texttoshow = ['1,',num2str(size(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,2))];
            app.EventNumberSelectionEditField.Value = texttoshow;
            
            spaceindicie = strfind(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange," ");
            app.TimearoundEvent(1) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(1:spaceindicie(1)-1));
            app.TimearoundEvent(2) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(spaceindicie(1)+1:end));

            commaindicie = strfind(app.EventNumberSelectionEditField.Value,",");
            EventNr(1) = str2double(app.EventNumberSelectionEditField.Value(1:commaindicie-1));
            EventNr(2) = str2double(app.EventNumberSelectionEditField.Value(commaindicie+1:end));

            %% Plot ERP
            
            if strcmp(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataType,"Preprocessed") && isfield(app.EventRelatedWindow.Mainapp.Data.Info,'DownsampledSampleRate')
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate:app.TimearoundEvent(2);
            else
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate:app.TimearoundEvent(2);
            end

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                [~,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.EventRelatedWindow.Mainapp.Data.EventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.EventRelatedWindow.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value);
            else
                [~,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.EventRelatedWindow.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value);
            end

        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.EventRelatedWindow.Mainapp.EventLFPERP = [];

            if ~isempty(app.EventRelatedWindow.Mainapp.ProbeViewWindowHandle) % delete option from probe view 
                for i = 1:length(app.EventRelatedWindow.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                    if strcmp(app.EventRelatedWindow.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Event ERP Window')
                        app.EventRelatedWindow.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items(i) = [];
                        break;
                    end
                end
            else % if no probe view available
 
            end

            delete(app)
        end

        % Value changed function: EventNumberSelectionEditField
        function EventNumberSelectionEditFieldValueChanged(app, event)
            %% Check if user inputs are correct
            
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                
                [app.EventNumberSelectionEditField.Value] = Utility_SimpleCheckInputs(app.EventNumberSelectionEditField.Value,"Two",strcat('1,',num2str(size(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,2))),1,0);
            else
                
                [app.EventNumberSelectionEditField.Value] = Utility_SimpleCheckInputs(app.EventNumberSelectionEditField.Value,"Two",strcat('1,',num2str(size(app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData,2))),1,0);
            end
            
            commaindicie = strfind(app.EventNumberSelectionEditField.Value,",");
            EventNr(1) = str2double(app.EventNumberSelectionEditField.Value(1:commaindicie-1));
            EventNr(2) = str2double(app.EventNumberSelectionEditField.Value(commaindicie+1:end));
            
            spaceindicie = strfind(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange," ");
            app.TimearoundEvent(1) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(1:spaceindicie-1));
            app.TimearoundEvent(2) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(spaceindicie+1:end));
            
            %% Plot ERP
            
            if strcmp(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataType,"Preprocessed") && isfield(app.EventRelatedWindow.Mainapp.Data.Info,'DownsampledSampleRate')
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate:app.TimearoundEvent(2);
            else
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate:app.TimearoundEvent(2);
            end

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                [~,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.EventRelatedWindow.Mainapp.Data.EventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.EventRelatedWindow.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value);
            else
                [~,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.EventRelatedWindow.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value);
            end
        end

        % Value changed function: ChannelSelectionDropDown
        function ChannelSelectionDropDownValueChanged(app, event)
            %% Check if user inputs are correct
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                [app.EventRelatedWindow.Mainapp.ActiveChannelDropDown.Value] = Utility_SimpleCheckInputs(app.EventRelatedWindow.Mainapp.ActiveChannelDropDown.Value,"Two",strcat('1,',num2str(size(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,1))),1,0);
            else
                [app.EventRelatedWindow.Mainapp.ActiveChannelDropDown.Value] = Utility_SimpleCheckInputs(app.EventRelatedWindow.Mainapp.ActiveChannelDropDown.Value,"Two",strcat('1,',num2str(size(app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData,1))),1,0);
            end

            spaceindicie = strfind(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange," ");
            app.TimearoundEvent(1) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(1:spaceindicie-1));
            app.TimearoundEvent(2) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(spaceindicie+1:end));

            commaindicie = strfind(app.EventNumberSelectionEditField.Value,",");
            EventNr(1) = str2double(app.EventNumberSelectionEditField.Value(1:commaindicie-1));
            EventNr(2) = str2double(app.EventNumberSelectionEditField.Value(commaindicie+1:end));
            %% Plot ERP

            if strcmp(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataType,"Preprocessed") && isfield(app.EventRelatedWindow.Mainapp.Data.Info,'DownsampledSampleRate')
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate:app.TimearoundEvent(2);
            else
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate:app.TimearoundEvent(2);
            end
            
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                [~,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.EventRelatedWindow.Mainapp.Data.EventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.EventRelatedWindow.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'SingleERPOnly',[],app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value);
            else
                [~,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.EventRelatedWindow.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'SingleERPOnly',[],app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value);
            end

        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            
            spaceindicie = strfind(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange," ");
            app.TimearoundEvent(1) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(1:spaceindicie-1));
            app.TimearoundEvent(2) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(spaceindicie+1:end));

            commaindicie = strfind(app.EventNumberSelectionEditField.Value,",");
            EventNr(1) = str2double(app.EventNumberSelectionEditField.Value(1:commaindicie-1));
            EventNr(2) = str2double(app.EventNumberSelectionEditField.Value(commaindicie+1:end));
            %% Plot ERP

            if strcmp(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataType,"Preprocessed") && isfield(app.EventRelatedWindow.Mainapp.Data.Info,'DownsampledSampleRate')
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate:app.TimearoundEvent(2);
            else
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate:app.TimearoundEvent(2);
            end
            
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                [~,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.EventRelatedWindow.Mainapp.Data.EventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.EventRelatedWindow.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'MultipleERPOnly',[],app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value);
            else
                [~,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.EventRelatedWindow.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'MultipleERPOnly',[],app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value);
            end

        end

        % Value changed function: DataTypeDropDown
        function DataTypeDropDownValueChanged(app, event)
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                texttoshow = ['1,',num2str(size(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,2))];
                app.EventNumberSelectionEditField.Value = texttoshow;
                Placeholder = {};
                app.ChannelSelectionDropDown_2.Items = Placeholder;
                for nchannel = 1:size(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,1)
                    app.ChannelSelectionDropDown_2.Items{nchannel} = num2str(nchannel);
                end
            else
                texttoshow = ['1,',num2str(size(app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData,2))];
                app.EventNumberSelectionEditField.Value = texttoshow;
                Placeholder = {};
                app.ChannelSelectionDropDown_2.Items = Placeholder;
                for nevents = 1:size(app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData)
                    app.ChannelSelectionDropDown_2.Items{nevents} = num2str(nevents);
                end
            end

            spaceindicie = strfind(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange," ");
            app.TimearoundEvent(1) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(1:spaceindicie-1));
            app.TimearoundEvent(2) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(spaceindicie+1:end));

            commaindicie = strfind(app.EventNumberSelectionEditField.Value,",");
            EventNr(1) = str2double(app.EventNumberSelectionEditField.Value(1:commaindicie-1));
            EventNr(2) = str2double(app.EventNumberSelectionEditField.Value(commaindicie+1:end));

            %% Plot ERP
            
            if strcmp(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataType,"Preprocessed") && isfield(app.EventRelatedWindow.Mainapp.Data.Info,'DownsampledSampleRate')
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate:app.TimearoundEvent(2);
            else
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate:app.TimearoundEvent(2);
            end
            
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                [~,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.EventRelatedWindow.Mainapp.Data.EventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.EventRelatedWindow.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value);
            else
                [~,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.EventRelatedWindow.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value);
            end
        end

        % Menu selected function: PNGMenu
        function PNGMenuSelected(app, event)
            [file, path] = uiputfile('*.png', 'Save as');

            exportapp(app.EventRelatedAnalysisUIFigure,fullfile(path,file)) 
        end

        % Menu selected function: JPGMenu
        function JPGMenuSelected(app, event)
            [file, path] = uiputfile('*.jpg', 'Save as');

            exportapp(app.EventRelatedAnalysisUIFigure,fullfile(path,file)) 
        end

        % Menu selected function: ERPoverEventsMenu
        function ERPoverEventsMenuSelected(app, event)
            
        end

        % Menu selected function: ERPoverChannelMenu
        function ERPoverChannelMenuSelected(app, event)
            
        end

        % Menu selected function: matMenu
        function matMenuSelected(app, event)
            TimeRangePlot = app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.Data,".mat",app.EventRelatedWindow.Mainapp.executableFolder,TimeRangePlot,StartTime,"Event Related Potential over Events");
        end

        % Menu selected function: txtMenu
        function txtMenuSelected(app, event)
            TimeRangePlot = app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.Data,".txt",app.EventRelatedWindow.Mainapp.executableFolder,TimeRangePlot,StartTime,"Event Related Potential over Events");
        end

        % Menu selected function: csvMenu
        function csvMenuSelected(app, event)
            TimeRangePlot = app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.Data,".csv",app.EventRelatedWindow.Mainapp.executableFolder,TimeRangePlot,StartTime,"Event Related Potential over Events");
        end

        % Menu selected function: matMenu_2
        function matMenu_2Selected(app, event)
            TimeRangePlot = app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.Data,".mat",app.EventRelatedWindow.Mainapp.executableFolder,TimeRangePlot,StartTime,"Event Related Potential over Channel");
        end

        % Menu selected function: txtMenu_2
        function txtMenu_2Selected(app, event)
            TimeRangePlot = app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.Data,".txt",app.EventRelatedWindow.Mainapp.executableFolder,TimeRangePlot,StartTime,"Event Related Potential over Channel");
        end

        % Menu selected function: csvMenu_2
        function csvMenu_2Selected(app, event)
            TimeRangePlot = app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.Data,".csv",app.EventRelatedWindow.Mainapp.executableFolder,TimeRangePlot,StartTime,"Event Related Potential over Channel");
        end

        % Menu selected function: ChangeSpacingLimitsMenu
        function ChangeSpacingLimitsMenuSelected(app, event)
            Channel_Spacing_Selection(app);
        end

        % Menu selected function: FontSizeMenu
        function FontSizeMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'fontsize',app.EventRelatedWindow.Mainapp,"SingleERPWindowDataFontSize");
            waitfor(h);

            app.UIAxes.FontSize = app.EventRelatedWindow.Mainapp.PlotAppearance.ERPWindow.SingleERP.FontSize;
            
            spaceindicie = strfind(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange," ");
            app.TimearoundEvent(1) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(1:spaceindicie-1));
            app.TimearoundEvent(2) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(spaceindicie+1:end));

            commaindicie = strfind(app.EventNumberSelectionEditField.Value,",");
            EventNr(1) = str2double(app.EventNumberSelectionEditField.Value(1:commaindicie-1));
            EventNr(2) = str2double(app.EventNumberSelectionEditField.Value(commaindicie+1:end));

            %% Plot ERP

            if strcmp(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataType,"Preprocessed") && isfield(app.EventRelatedWindow.Mainapp.Data.Info,'DownsampledSampleRate')
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate:app.TimearoundEvent(2);
            else
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate:app.TimearoundEvent(2);
            end
            
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                [~,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.EventRelatedWindow.Mainapp.Data.EventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.EventRelatedWindow.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value);
            else
                [~,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.EventRelatedWindow.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value);
            end
        end

        % Menu selected function: XLabelMenu
        function XLabelMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'xlabel',app.EventRelatedWindow.Mainapp,"SingleERPWindowXLabel");
            waitfor(h);
            
            spaceindicie = strfind(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange," ");
            app.TimearoundEvent(1) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(1:spaceindicie-1));
            app.TimearoundEvent(2) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(spaceindicie+1:end));

            commaindicie = strfind(app.EventNumberSelectionEditField.Value,",");
            EventNr(1) = str2double(app.EventNumberSelectionEditField.Value(1:commaindicie-1));
            EventNr(2) = str2double(app.EventNumberSelectionEditField.Value(commaindicie+1:end));
            %% Plot ERP

            if strcmp(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataType,"Preprocessed") && isfield(app.EventRelatedWindow.Mainapp.Data.Info,'DownsampledSampleRate')
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate:app.TimearoundEvent(2);
            else
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate:app.TimearoundEvent(2);
            end

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                [~,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.EventRelatedWindow.Mainapp.Data.EventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.EventRelatedWindow.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value);
            else
                [~,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.EventRelatedWindow.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value);
            end
        end

        % Menu selected function: YLabelMenu
        function YLabelMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'ylabel',app.EventRelatedWindow.Mainapp,"SingleERPWindowYLabel");
            waitfor(h);
            
            spaceindicie = strfind(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange," ");
            app.TimearoundEvent(1) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(1:spaceindicie-1));
            app.TimearoundEvent(2) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(spaceindicie+1:end));

            commaindicie = strfind(app.EventNumberSelectionEditField.Value,",");
            EventNr(1) = str2double(app.EventNumberSelectionEditField.Value(1:commaindicie-1));
            EventNr(2) = str2double(app.EventNumberSelectionEditField.Value(commaindicie+1:end));
            %% Plot ERP

            if strcmp(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataType,"Preprocessed") && isfield(app.EventRelatedWindow.Mainapp.Data.Info,'DownsampledSampleRate')
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate:app.TimearoundEvent(2);
            else
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate:app.TimearoundEvent(2);
            end

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                [~,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.EventRelatedWindow.Mainapp.Data.EventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.EventRelatedWindow.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value);
            else
                [~,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.EventRelatedWindow.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value);
            end
        end

        % Menu selected function: FontSizeMenu_3
        function FontSizeMenu_3Selected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes_2,'fontsize',app.EventRelatedWindow.Mainapp,"MultipleERPWindowDataFontSize");
            waitfor(h);

            app.UIAxes_2.FontSize = app.EventRelatedWindow.Mainapp.PlotAppearance.ERPWindow.MultipleERP.FontSize;
            
            spaceindicie = strfind(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange," ");
            app.TimearoundEvent(1) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(1:spaceindicie-1));
            app.TimearoundEvent(2) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(spaceindicie+1:end));

            commaindicie = strfind(app.EventNumberSelectionEditField.Value,",");
            EventNr(1) = str2double(app.EventNumberSelectionEditField.Value(1:commaindicie-1));
            EventNr(2) = str2double(app.EventNumberSelectionEditField.Value(commaindicie+1:end));

            %% Plot ERP

            if strcmp(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataType,"Preprocessed") && isfield(app.EventRelatedWindow.Mainapp.Data.Info,'DownsampledSampleRate')
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate:app.TimearoundEvent(2);
            else
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate:app.TimearoundEvent(2);
            end
            
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                [~,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.EventRelatedWindow.Mainapp.Data.EventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.EventRelatedWindow.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value);
            else
                [~,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.EventRelatedWindow.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value);
            end
        end

        % Menu selected function: LineWidthMeanMenu_2
        function LineWidthMeanMenu_2Selected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes_2,'linewidth',app.EventRelatedWindow.Mainapp,"MultipleERPLineWidthMean");
            waitfor(h);

            spaceindicie = strfind(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange," ");
            app.TimearoundEvent(1) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(1:spaceindicie-1));
            app.TimearoundEvent(2) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(spaceindicie+1:end));

            commaindicie = strfind(app.EventNumberSelectionEditField.Value,",");
            EventNr(1) = str2double(app.EventNumberSelectionEditField.Value(1:commaindicie-1));
            EventNr(2) = str2double(app.EventNumberSelectionEditField.Value(commaindicie+1:end));
            %% Plot ERP

            if strcmp(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataType,"Preprocessed") && isfield(app.EventRelatedWindow.Mainapp.Data.Info,'DownsampledSampleRate')
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate:app.TimearoundEvent(2);
            else
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate:app.TimearoundEvent(2);
            end

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                [~,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.EventRelatedWindow.Mainapp.Data.EventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.EventRelatedWindow.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value);
            else
                [~,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.EventRelatedWindow.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value);
            end
        end

        % Menu selected function: LineWidthTriggerMenu_2
        function LineWidthTriggerMenu_2Selected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes_2,'linewidth',app.EventRelatedWindow.Mainapp,"MultipleERPLineWidthTrigger");
            waitfor(h);

            spaceindicie = strfind(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange," ");
            app.TimearoundEvent(1) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(1:spaceindicie-1));
            app.TimearoundEvent(2) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(spaceindicie+1:end));

            commaindicie = strfind(app.EventNumberSelectionEditField.Value,",");
            EventNr(1) = str2double(app.EventNumberSelectionEditField.Value(1:commaindicie-1));
            EventNr(2) = str2double(app.EventNumberSelectionEditField.Value(commaindicie+1:end));
            %% Plot ERP

            if strcmp(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataType,"Preprocessed") && isfield(app.EventRelatedWindow.Mainapp.Data.Info,'DownsampledSampleRate')
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate:app.TimearoundEvent(2);
            else
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate:app.TimearoundEvent(2);
            end

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                [~,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.EventRelatedWindow.Mainapp.Data.EventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.EventRelatedWindow.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value);
            else
                [~,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.EventRelatedWindow.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value);
            end
        end

        % Menu selected function: ColorTriggerMenu_2
        function ColorTriggerMenu_2Selected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.EventRelatedWindow.Mainapp.PlotAppearance.ERPWindow.MultipleERP.TriggerColor = selectedColor;
            end

            spaceindicie = strfind(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange," ");
            app.TimearoundEvent(1) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(1:spaceindicie-1));
            app.TimearoundEvent(2) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(spaceindicie+1:end));

            commaindicie = strfind(app.EventNumberSelectionEditField.Value,",");
            EventNr(1) = str2double(app.EventNumberSelectionEditField.Value(1:commaindicie-1));
            EventNr(2) = str2double(app.EventNumberSelectionEditField.Value(commaindicie+1:end));
            %% Plot ERP

            if strcmp(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataType,"Preprocessed") && isfield(app.EventRelatedWindow.Mainapp.Data.Info,'DownsampledSampleRate')
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate:app.TimearoundEvent(2);
            else
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate:app.TimearoundEvent(2);
            end
            
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                [~,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.EventRelatedWindow.Mainapp.Data.EventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.EventRelatedWindow.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value);
            else
                [~,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.EventRelatedWindow.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value);
            end
        end

        % Menu selected function: BackgorundColorMenu_2
        function BackgorundColorMenu_2Selected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.EventRelatedWindow.Mainapp.PlotAppearance.ERPWindow.MultipleERP.BackgroundColor = selectedColor;
                app.UIAxes_2.Color = app.EventRelatedWindow.Mainapp.PlotAppearance.ERPWindow.MultipleERP.BackgroundColor;
            end
        end

        % Menu selected function: XLabelMenu_3
        function XLabelMenu_3Selected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes_2,'xlabel',app.EventRelatedWindow.Mainapp,"MultipleERPWindowXLabel");
            waitfor(h);
            
            spaceindicie = strfind(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange," ");
            app.TimearoundEvent(1) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(1:spaceindicie-1));
            app.TimearoundEvent(2) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(spaceindicie+1:end));

            commaindicie = strfind(app.EventNumberSelectionEditField.Value,",");
            EventNr(1) = str2double(app.EventNumberSelectionEditField.Value(1:commaindicie-1));
            EventNr(2) = str2double(app.EventNumberSelectionEditField.Value(commaindicie+1:end));
            %% Plot ERP

            if strcmp(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataType,"Preprocessed") && isfield(app.EventRelatedWindow.Mainapp.Data.Info,'DownsampledSampleRate')
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate:app.TimearoundEvent(2);
            else
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate:app.TimearoundEvent(2);
            end

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                [~,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.EventRelatedWindow.Mainapp.Data.EventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.EventRelatedWindow.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value);
            else
                [~,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.EventRelatedWindow.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value);
            end
        end

        % Menu selected function: YLabelMenu_3
        function YLabelMenu_3Selected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes_2,'ylabel',app.EventRelatedWindow.Mainapp,"MultipleERPWindowYLabel");
            waitfor(h);
            
            spaceindicie = strfind(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange," ");
            app.TimearoundEvent(1) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(1:spaceindicie-1));
            app.TimearoundEvent(2) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(spaceindicie+1:end));

            commaindicie = strfind(app.EventNumberSelectionEditField.Value,",");
            EventNr(1) = str2double(app.EventNumberSelectionEditField.Value(1:commaindicie-1));
            EventNr(2) = str2double(app.EventNumberSelectionEditField.Value(commaindicie+1:end));
            %% Plot ERP

            if strcmp(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataType,"Preprocessed") && isfield(app.EventRelatedWindow.Mainapp.Data.Info,'DownsampledSampleRate')
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate:app.TimearoundEvent(2);
            else
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate:app.TimearoundEvent(2);
            end

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                [~,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.EventRelatedWindow.Mainapp.Data.EventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.EventRelatedWindow.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value);
            else
                [~,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.EventRelatedWindow.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value);
            end
        end

        % Menu selected function: LineWidthEventsMenu_3
        function LineWidthEventsMenu_3Selected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'linewidth',app.EventRelatedWindow.Mainapp,"SingleERPLineWidthEvents");
            waitfor(h);

            spaceindicie = strfind(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange," ");
            app.TimearoundEvent(1) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(1:spaceindicie-1));
            app.TimearoundEvent(2) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(spaceindicie+1:end));

            commaindicie = strfind(app.EventNumberSelectionEditField.Value,",");
            EventNr(1) = str2double(app.EventNumberSelectionEditField.Value(1:commaindicie-1));
            EventNr(2) = str2double(app.EventNumberSelectionEditField.Value(commaindicie+1:end));
            %% Plot ERP

            if strcmp(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataType,"Preprocessed") && isfield(app.EventRelatedWindow.Mainapp.Data.Info,'DownsampledSampleRate')
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate:app.TimearoundEvent(2);
            else
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate:app.TimearoundEvent(2);
            end

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                [~,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.EventRelatedWindow.Mainapp.Data.EventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.EventRelatedWindow.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value);
            else
                [~,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.EventRelatedWindow.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value);
            end
        end

        % Menu selected function: LineWidthMeanMenu
        function LineWidthMeanMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'linewidth',app.EventRelatedWindow.Mainapp,"SingleERPLineWidthMean");
            waitfor(h);

            spaceindicie = strfind(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange," ");
            app.TimearoundEvent(1) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(1:spaceindicie-1));
            app.TimearoundEvent(2) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(spaceindicie+1:end));

            commaindicie = strfind(app.EventNumberSelectionEditField.Value,",");
            EventNr(1) = str2double(app.EventNumberSelectionEditField.Value(1:commaindicie-1));
            EventNr(2) = str2double(app.EventNumberSelectionEditField.Value(commaindicie+1:end));
            %% Plot ERP

            if strcmp(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataType,"Preprocessed") && isfield(app.EventRelatedWindow.Mainapp.Data.Info,'DownsampledSampleRate')
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate:app.TimearoundEvent(2);
            else
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate:app.TimearoundEvent(2);
            end

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                [~,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.EventRelatedWindow.Mainapp.Data.EventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.EventRelatedWindow.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value);
            else
                [~,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.EventRelatedWindow.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value);
            end
        end

        % Menu selected function: LineWidthTriggerMenu
        function LineWidthTriggerMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'linewidth',app.EventRelatedWindow.Mainapp,"SingleERPLineWidthTrigger");
            waitfor(h);

            spaceindicie = strfind(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange," ");
            app.TimearoundEvent(1) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(1:spaceindicie-1));
            app.TimearoundEvent(2) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(spaceindicie+1:end));

            commaindicie = strfind(app.EventNumberSelectionEditField.Value,",");
            EventNr(1) = str2double(app.EventNumberSelectionEditField.Value(1:commaindicie-1));
            EventNr(2) = str2double(app.EventNumberSelectionEditField.Value(commaindicie+1:end));
            %% Plot ERP

            if strcmp(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataType,"Preprocessed") && isfield(app.EventRelatedWindow.Mainapp.Data.Info,'DownsampledSampleRate')
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate:app.TimearoundEvent(2);
            else
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate:app.TimearoundEvent(2);
            end

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                [~,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.EventRelatedWindow.Mainapp.Data.EventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.EventRelatedWindow.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value);
            else
                [~,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.EventRelatedWindow.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value);
            end
        end

        % Menu selected function: ColorEventsMenu_3
        function ColorEventsMenu_3Selected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.EventRelatedWindow.Mainapp.PlotAppearance.ERPWindow.SingleERP.EventColor = selectedColor;
            end

            spaceindicie = strfind(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange," ");
            app.TimearoundEvent(1) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(1:spaceindicie-1));
            app.TimearoundEvent(2) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(spaceindicie+1:end));

            commaindicie = strfind(app.EventNumberSelectionEditField.Value,",");
            EventNr(1) = str2double(app.EventNumberSelectionEditField.Value(1:commaindicie-1));
            EventNr(2) = str2double(app.EventNumberSelectionEditField.Value(commaindicie+1:end));
            %% Plot ERP

            if strcmp(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataType,"Preprocessed") && isfield(app.EventRelatedWindow.Mainapp.Data.Info,'DownsampledSampleRate')
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate:app.TimearoundEvent(2);
            else
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate:app.TimearoundEvent(2);
            end

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                [~,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.EventRelatedWindow.Mainapp.Data.EventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.EventRelatedWindow.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value);
            else
                [~,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.EventRelatedWindow.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value);
            end
        end

        % Menu selected function: ColorMeanMenu
        function ColorMeanMenuSelected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.EventRelatedWindow.Mainapp.PlotAppearance.ERPWindow.SingleERP.MeanColor = selectedColor;
            end

            spaceindicie = strfind(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange," ");
            app.TimearoundEvent(1) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(1:spaceindicie-1));
            app.TimearoundEvent(2) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(spaceindicie+1:end));

            commaindicie = strfind(app.EventNumberSelectionEditField.Value,",");
            EventNr(1) = str2double(app.EventNumberSelectionEditField.Value(1:commaindicie-1));
            EventNr(2) = str2double(app.EventNumberSelectionEditField.Value(commaindicie+1:end));
            %% Plot ERP

            if strcmp(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataType,"Preprocessed") && isfield(app.EventRelatedWindow.Mainapp.Data.Info,'DownsampledSampleRate')
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate:app.TimearoundEvent(2);
            else
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate:app.TimearoundEvent(2);
            end
            
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                [~,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.EventRelatedWindow.Mainapp.Data.EventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.EventRelatedWindow.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value);
            else
                [~,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.EventRelatedWindow.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value);
            end
        end

        % Menu selected function: ColorTriggerMenu
        function ColorTriggerMenuSelected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.EventRelatedWindow.Mainapp.PlotAppearance.ERPWindow.SingleERP.TriggerColor = selectedColor;
            end

            spaceindicie = strfind(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange," ");
            app.TimearoundEvent(1) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(1:spaceindicie-1));
            app.TimearoundEvent(2) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(spaceindicie+1:end));

            commaindicie = strfind(app.EventNumberSelectionEditField.Value,",");
            EventNr(1) = str2double(app.EventNumberSelectionEditField.Value(1:commaindicie-1));
            EventNr(2) = str2double(app.EventNumberSelectionEditField.Value(commaindicie+1:end));
            %% Plot ERP

            if strcmp(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataType,"Preprocessed") && isfield(app.EventRelatedWindow.Mainapp.Data.Info,'DownsampledSampleRate')
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate:app.TimearoundEvent(2);
            else
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate:app.TimearoundEvent(2);
            end

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                [~,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.EventRelatedWindow.Mainapp.Data.EventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.EventRelatedWindow.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value);
            else
                [~,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.EventRelatedWindow.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value);
            end
        end

        % Menu selected function: BackgorundColorMenu
        function BackgorundColorMenuSelected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.EventRelatedWindow.Mainapp.PlotAppearance.ERPWindow.SingleERP.BackgroundColor = selectedColor;
                app.UIAxes.Color = app.EventRelatedWindow.Mainapp.PlotAppearance.ERPWindow.SingleERP.BackgroundColor;
                app.UIAxes_2.Color = app.EventRelatedWindow.Mainapp.PlotAppearance.ERPWindow.MultipleERP.BackgroundColor;
                app.UIAxes_2.FontSize = app.EventRelatedWindow.Mainapp.PlotAppearance.ERPWindow.MultipleERP.FontSize;
            end
        end

        % Menu selected function: RESETMenu
        function RESETMenuSelected(app, event)
            app.UIAxes.NextPlot = "replace"; 
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add"; 

            [app.EventRelatedWindow.Mainapp.PlotAppearance] = Organize_Set_Standard_PlotAppearance("ERPPlot",app.EventRelatedWindow.Mainapp.PlotAppearance);

            spaceindicie = strfind(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange," ");
            app.TimearoundEvent(1) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(1:spaceindicie-1));
            app.TimearoundEvent(2) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(spaceindicie+1:end));

            commaindicie = strfind(app.EventNumberSelectionEditField.Value,",");
            EventNr(1) = str2double(app.EventNumberSelectionEditField.Value(1:commaindicie-1));
            EventNr(2) = str2double(app.EventNumberSelectionEditField.Value(commaindicie+1:end));
            %% Plot ERP

            if strcmp(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataType,"Preprocessed") && isfield(app.EventRelatedWindow.Mainapp.Data.Info,'DownsampledSampleRate')
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate:app.TimearoundEvent(2);
            else
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate:app.TimearoundEvent(2);
            end

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                [~,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.EventRelatedWindow.Mainapp.Data.EventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.EventRelatedWindow.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value);
            else
                [~,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.EventRelatedWindow.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value);
            end
        end

        % Menu selected function: RESETMenu_3
        function RESETMenu_3Selected(app, event)
            app.UIAxes_2.NextPlot = "replace"; 
            plot(app.UIAxes_2,0,0);
            app.UIAxes_2.NextPlot = "add"; 

            [app.EventRelatedWindow.Mainapp.PlotAppearance] = Organize_Set_Standard_PlotAppearance("ERPPlot",app.EventRelatedWindow.Mainapp.PlotAppearance);

            spaceindicie = strfind(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange," ");
            app.TimearoundEvent(1) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(1:spaceindicie-1));
            app.TimearoundEvent(2) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(spaceindicie+1:end));

            commaindicie = strfind(app.EventNumberSelectionEditField.Value,",");
            EventNr(1) = str2double(app.EventNumberSelectionEditField.Value(1:commaindicie-1));
            EventNr(2) = str2double(app.EventNumberSelectionEditField.Value(commaindicie+1:end));
            %% Plot ERP

            if strcmp(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataType,"Preprocessed") && isfield(app.EventRelatedWindow.Mainapp.Data.Info,'DownsampledSampleRate')
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate:app.TimearoundEvent(2);
            else
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate:app.TimearoundEvent(2);
            end

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                [~,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.EventRelatedWindow.Mainapp.Data.EventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.EventRelatedWindow.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value);
            else
                [~,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.EventRelatedWindow.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value);
            end
        end

        % Menu selected function: SaveCostumeAppearanceMenu
        function SaveCostumeAppearanceMenuSelected(app, event)
            app.EventRelatedWindow.Mainapp.PlotAppearance = Utility_Save_Load_Delete_Plot_Appearance(app.EventRelatedWindow.Mainapp.PlotAppearance,app.EventRelatedWindow.Mainapp.executableFolder,"Save");
        end

        % Menu selected function: DeleteCostumeAppearanceMenu
        function DeleteCostumeAppearanceMenuSelected(app, event)
            app.EventRelatedWindow.Mainapp.PlotAppearance = Utility_Save_Load_Delete_Plot_Appearance(app.EventRelatedWindow.Mainapp.PlotAppearance,app.EventRelatedWindow.Mainapp.executableFolder,"Delete");
        end

        % Close request function: EventRelatedAnalysisUIFigure
        function EventRelatedAnalysisUIFigureCloseRequest(app, event)
            app.EventRelatedWindow.Mainapp.EventLFPERP = [];

            if ~isempty(app.EventRelatedWindow.Mainapp.ProbeViewWindowHandle) % delete option from probe view 
                for i = 1:length(app.EventRelatedWindow.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                    if strcmp(app.EventRelatedWindow.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Event ERP Window')
                        app.EventRelatedWindow.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items(i) = [];
                        break;
                    end
                end
            else % if no probe view available
 
            end

            delete(app)
            
        end

        % Value changed function: ChannelSelectionDropDown_2
        function ChannelSelectionDropDown_2ValueChanged(app, event)
            %% Check if user inputs are correct
            
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                
                [app.EventNumberSelectionEditField.Value] = Utility_SimpleCheckInputs(app.EventNumberSelectionEditField.Value,"Two",strcat('1,',num2str(size(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,2))),1,0);
            else
                
                [app.EventNumberSelectionEditField.Value] = Utility_SimpleCheckInputs(app.EventNumberSelectionEditField.Value,"Two",strcat('1,',num2str(size(app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData,2))),1,0);
            end
            
            commaindicie = strfind(app.EventNumberSelectionEditField.Value,",");
            EventNr(1) = str2double(app.EventNumberSelectionEditField.Value(1:commaindicie-1));
            EventNr(2) = str2double(app.EventNumberSelectionEditField.Value(commaindicie+1:end));
            
            spaceindicie = strfind(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange," ");
            app.TimearoundEvent(1) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(1:spaceindicie-1));
            app.TimearoundEvent(2) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(spaceindicie+1:end));
            
            %% Plot ERP
            
            if strcmp(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataType,"Preprocessed") && isfield(app.EventRelatedWindow.Mainapp.Data.Info,'DownsampledSampleRate')
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate:app.TimearoundEvent(2);
            else
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate:app.TimearoundEvent(2);
            end

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                [~,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.EventRelatedWindow.Mainapp.Data.EventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.EventRelatedWindow.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value);
            else
                [~,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.EventRelatedWindow.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value);
            end
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.EventRelatedAnalysisUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {819, 819};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EventRelatedAnalysisUIFigure = uifigure('Visible', 'off');
            app.EventRelatedAnalysisUIFigure.Name = 'Event Related Analysis';
            app.EventRelatedAnalysisUIFigure.Icon = 'Logo.png';
            app.EventRelatedAnalysisUIFigure.CloseRequestFcn = createCallbackFcn(app, @EventRelatedAnalysisUIFigureCloseRequest, true);
            app.EventRelatedAnalysisUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.SnapshotMenu = uimenu(app.EventRelatedAnalysisUIFigure);
            app.SnapshotMenu.Text = '   Snapshot   ';
            app.SaveSnapshotofWindowMenu = uimenu(app.SnapshotMenu);
            app.SaveSnapshotofWindowMenu.Text = 'Save Snapshot of Window';
            app.PNGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.PNGMenu.MenuSelectedFcn = createCallbackFcn(app, @PNGMenuSelected, true);
            app.PNGMenu.Text = 'PNG';
            app.JPGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.JPGMenu.MenuSelectedFcn = createCallbackFcn(app, @JPGMenuSelected, true);
            app.JPGMenu.Text = 'JPG';
            app.PlotMenu = uimenu(app.EventRelatedAnalysisUIFigure);
            app.PlotMenu.Text = '   Plot   ';
            app.ChangePlotPropertiesMenu = uimenu(app.PlotMenu);
            app.ChangePlotPropertiesMenu.Text = 'Change Plot Properties';
            app.SingleERPPlotMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.SingleERPPlotMenu.Text = 'Single ERP Plot';
            app.FontSizeMenu = uimenu(app.SingleERPPlotMenu);
            app.FontSizeMenu.MenuSelectedFcn = createCallbackFcn(app, @FontSizeMenuSelected, true);
            app.FontSizeMenu.Text = 'FontSize';
            app.LineWidthEventsMenu_3 = uimenu(app.SingleERPPlotMenu);
            app.LineWidthEventsMenu_3.MenuSelectedFcn = createCallbackFcn(app, @LineWidthEventsMenu_3Selected, true);
            app.LineWidthEventsMenu_3.Text = 'LineWidth Events';
            app.LineWidthMeanMenu = uimenu(app.SingleERPPlotMenu);
            app.LineWidthMeanMenu.MenuSelectedFcn = createCallbackFcn(app, @LineWidthMeanMenuSelected, true);
            app.LineWidthMeanMenu.Text = 'LineWidth Mean';
            app.LineWidthTriggerMenu = uimenu(app.SingleERPPlotMenu);
            app.LineWidthTriggerMenu.MenuSelectedFcn = createCallbackFcn(app, @LineWidthTriggerMenuSelected, true);
            app.LineWidthTriggerMenu.Text = 'LineWidth Trigger';
            app.ColorEventsMenu_3 = uimenu(app.SingleERPPlotMenu);
            app.ColorEventsMenu_3.MenuSelectedFcn = createCallbackFcn(app, @ColorEventsMenu_3Selected, true);
            app.ColorEventsMenu_3.Text = 'Color Events';
            app.ColorMeanMenu = uimenu(app.SingleERPPlotMenu);
            app.ColorMeanMenu.MenuSelectedFcn = createCallbackFcn(app, @ColorMeanMenuSelected, true);
            app.ColorMeanMenu.Text = 'Color Mean';
            app.ColorTriggerMenu = uimenu(app.SingleERPPlotMenu);
            app.ColorTriggerMenu.MenuSelectedFcn = createCallbackFcn(app, @ColorTriggerMenuSelected, true);
            app.ColorTriggerMenu.Text = 'Color Trigger';
            app.BackgorundColorMenu = uimenu(app.SingleERPPlotMenu);
            app.BackgorundColorMenu.MenuSelectedFcn = createCallbackFcn(app, @BackgorundColorMenuSelected, true);
            app.BackgorundColorMenu.Text = 'Backgorund Color';
            app.XLabelMenu = uimenu(app.SingleERPPlotMenu);
            app.XLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @XLabelMenuSelected, true);
            app.XLabelMenu.Text = 'XLabel';
            app.YLabelMenu = uimenu(app.SingleERPPlotMenu);
            app.YLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @YLabelMenuSelected, true);
            app.YLabelMenu.Text = 'YLabel';
            app.RESETMenu = uimenu(app.SingleERPPlotMenu);
            app.RESETMenu.MenuSelectedFcn = createCallbackFcn(app, @RESETMenuSelected, true);
            app.RESETMenu.Text = 'RESET';
            app.MultipleERPPlotMenu_2 = uimenu(app.ChangePlotPropertiesMenu);
            app.MultipleERPPlotMenu_2.Text = 'Multiple ERP Plot';
            app.FontSizeMenu_3 = uimenu(app.MultipleERPPlotMenu_2);
            app.FontSizeMenu_3.MenuSelectedFcn = createCallbackFcn(app, @FontSizeMenu_3Selected, true);
            app.FontSizeMenu_3.Text = 'FontSize';
            app.LineWidthMeanMenu_2 = uimenu(app.MultipleERPPlotMenu_2);
            app.LineWidthMeanMenu_2.MenuSelectedFcn = createCallbackFcn(app, @LineWidthMeanMenu_2Selected, true);
            app.LineWidthMeanMenu_2.Text = 'LineWidth Mean';
            app.LineWidthTriggerMenu_2 = uimenu(app.MultipleERPPlotMenu_2);
            app.LineWidthTriggerMenu_2.MenuSelectedFcn = createCallbackFcn(app, @LineWidthTriggerMenu_2Selected, true);
            app.LineWidthTriggerMenu_2.Text = 'LineWidth Trigger';
            app.ColorTriggerMenu_2 = uimenu(app.MultipleERPPlotMenu_2);
            app.ColorTriggerMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ColorTriggerMenu_2Selected, true);
            app.ColorTriggerMenu_2.Text = 'Color Trigger';
            app.BackgorundColorMenu_2 = uimenu(app.MultipleERPPlotMenu_2);
            app.BackgorundColorMenu_2.MenuSelectedFcn = createCallbackFcn(app, @BackgorundColorMenu_2Selected, true);
            app.BackgorundColorMenu_2.Text = 'Backgorund Color';
            app.XLabelMenu_3 = uimenu(app.MultipleERPPlotMenu_2);
            app.XLabelMenu_3.MenuSelectedFcn = createCallbackFcn(app, @XLabelMenu_3Selected, true);
            app.XLabelMenu_3.Text = 'XLabel';
            app.YLabelMenu_3 = uimenu(app.MultipleERPPlotMenu_2);
            app.YLabelMenu_3.MenuSelectedFcn = createCallbackFcn(app, @YLabelMenu_3Selected, true);
            app.YLabelMenu_3.Text = 'YLabel';
            app.RESETMenu_3 = uimenu(app.MultipleERPPlotMenu_2);
            app.RESETMenu_3.MenuSelectedFcn = createCallbackFcn(app, @RESETMenu_3Selected, true);
            app.RESETMenu_3.Text = 'RESET';
            app.SaveCostumeAppearanceMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.SaveCostumeAppearanceMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveCostumeAppearanceMenuSelected, true);
            app.SaveCostumeAppearanceMenu.Text = 'Save Costume Appearance';
            app.DeleteCostumeAppearanceMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.DeleteCostumeAppearanceMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteCostumeAppearanceMenuSelected, true);
            app.DeleteCostumeAppearanceMenu.Text = 'Delete Costume Appearance';
            app.ExportDataMenu = uimenu(app.EventRelatedAnalysisUIFigure);
            app.ExportDataMenu.Text = '   Export Data   ';
            app.ERPoverEventsMenu = uimenu(app.ExportDataMenu);
            app.ERPoverEventsMenu.MenuSelectedFcn = createCallbackFcn(app, @ERPoverEventsMenuSelected, true);
            app.ERPoverEventsMenu.Text = 'ERP over Events';
            app.matMenu = uimenu(app.ERPoverEventsMenu);
            app.matMenu.MenuSelectedFcn = createCallbackFcn(app, @matMenuSelected, true);
            app.matMenu.Text = '.mat';
            app.txtMenu = uimenu(app.ERPoverEventsMenu);
            app.txtMenu.MenuSelectedFcn = createCallbackFcn(app, @txtMenuSelected, true);
            app.txtMenu.Text = '.txt';
            app.csvMenu = uimenu(app.ERPoverEventsMenu);
            app.csvMenu.MenuSelectedFcn = createCallbackFcn(app, @csvMenuSelected, true);
            app.csvMenu.Text = '.csv';
            app.ERPoverChannelMenu = uimenu(app.ExportDataMenu);
            app.ERPoverChannelMenu.MenuSelectedFcn = createCallbackFcn(app, @ERPoverChannelMenuSelected, true);
            app.ERPoverChannelMenu.Text = 'ERP over Channel';
            app.matMenu_2 = uimenu(app.ERPoverChannelMenu);
            app.matMenu_2.MenuSelectedFcn = createCallbackFcn(app, @matMenu_2Selected, true);
            app.matMenu_2.Text = '.mat';
            app.txtMenu_2 = uimenu(app.ERPoverChannelMenu);
            app.txtMenu_2.MenuSelectedFcn = createCallbackFcn(app, @txtMenu_2Selected, true);
            app.txtMenu_2.Text = '.txt';
            app.csvMenu_2 = uimenu(app.ERPoverChannelMenu);
            app.csvMenu_2.MenuSelectedFcn = createCallbackFcn(app, @csvMenu_2Selected, true);
            app.csvMenu_2.Text = '.csv';
            app.GridLayout = uigridlayout(app.EventRelatedAnalysisUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            app.UIAxes_2 = uiaxes(app.RightPanel);
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.YTickLabel = '';
            app.ERPAnalysisSettingsPanel = uipanel(app.RightPanel);
            app.ERPAnalysisSettingsPanel.Title = 'ERP Analysis Settings';
            app.ChannelLabel = uilabel(app.ERPAnalysisSettingsPanel);
            app.ChannelLabel.Enable = 'off';
            app.ChannelLabel.Visible = 'off';
            app.ChannelLabel.Text = {'Channel Selection'; '[from,to]'};
            app.ChannelSelectionDropDown = uieditfield(app.ERPAnalysisSettingsPanel, 'text');
            app.ChannelSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelSelectionDropDownValueChanged, true);
            app.ChannelSelectionDropDown.Editable = 'off';
            app.ChannelSelectionDropDown.Enable = 'off';
            app.ChannelSelectionDropDown.Visible = 'off';
            app.DataTypeDropDownLabel = uilabel(app.ERPAnalysisSettingsPanel);
            app.DataTypeDropDownLabel.Text = 'Data Type';
            app.DataTypeDropDown = uidropdown(app.ERPAnalysisSettingsPanel);
            app.DataTypeDropDown.Items = {};
            app.DataTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DataTypeDropDownValueChanged, true);
            app.DataTypeDropDown.Value = {};
            app.EventNumberSelectionEditFieldLabel = uilabel(app.ERPAnalysisSettingsPanel);
            app.EventNumberSelectionEditFieldLabel.Text = {'Event Number'; 'Selection'};
            app.EventNumberSelectionEditField = uieditfield(app.ERPAnalysisSettingsPanel, 'text');
            app.EventNumberSelectionEditField.ValueChangedFcn = createCallbackFcn(app, @EventNumberSelectionEditFieldValueChanged, true);
            app.ChannelSelectionDropDown_2Label = uilabel(app.ERPAnalysisSettingsPanel);
            app.ChannelSelectionDropDown_2Label.Text = {'Channel '; 'Selection'};
            app.ChannelSelectionDropDown_2 = uidropdown(app.ERPAnalysisSettingsPanel);
            app.ChannelSelectionDropDown_2.Items = {};
            app.ChannelSelectionDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ChannelSelectionDropDown_2ValueChanged, true);
            app.ChannelSelectionDropDown_2.Value = {};
            app.CloseButton = uibutton(app.RightPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.DataLineSpacingLabel = uilabel(app.RightPanel);
            app.DataLineSpacingLabel.Text = {'Data Line'; 'Spacing'};
            app.Slider = uislider(app.RightPanel);
            app.Slider.Limits = [0.001 0.1];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 0.001;
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = 'Logo.png';
            app.EventRelatedPotentialWindowLabel = uilabel(app.RightPanel);
            app.EventRelatedPotentialWindowLabel.Text = 'Event Related Potential Window';
            app.NeuromodLabel = uilabel(app.RightPanel);
            app.NeuromodLabel.Text = 'Neuromod';
            app.ContextMenu4 = uicontextmenu(app.EventRelatedAnalysisUIFigure);
            app.ChangeSpacingLimitsMenu = uimenu(app.ContextMenu4);
            app.ChangeSpacingLimitsMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeSpacingLimitsMenuSelected, true);
            app.ChangeSpacingLimitsMenu.Text = 'Change Spacing Limits';
            % Assign app.ContextMenu4
            app.Slider.ContextMenu = app.ContextMenu4;
            % Show the figure after all components are created
            app.EventRelatedAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ERP_Analysis_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.EventRelatedAnalysisUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.EventRelatedAnalysisUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EventRelatedAnalysisUIFigure)
        end
    end
end