classdef Event_Static_Power_Spectrum_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EventStaticSpectrumWindowUIFigure  matlab.ui.Figure
        SnapshotMenu                 matlab.ui.container.Menu
        SaveSnapshotofWindowMenu     matlab.ui.container.Menu
        PNGMenu                      matlab.ui.container.Menu
        JPGMenu                      matlab.ui.container.Menu
        PlotMenu_2                   matlab.ui.container.Menu
        Show2DimensionalPlotsMenu    matlab.ui.container.Menu
        Show3DimensionalPlotsMenu    matlab.ui.container.Menu
        ChangePlotPropertiesMenu     matlab.ui.container.Menu
        DataPlotMenu                 matlab.ui.container.Menu
        FontSizeMenu                 matlab.ui.container.Menu
        LineWidthDataMenu            matlab.ui.container.Menu
        ColorDataMenu                matlab.ui.container.Menu
        BackgorundColorMenu          matlab.ui.container.Menu
        XLabelMenu                   matlab.ui.container.Menu
        YLabelMenu                   matlab.ui.container.Menu
        RESETMenu                    matlab.ui.container.Menu
        SaveCostumeAppearanceMenu    matlab.ui.container.Menu
        DeleteCostumeAppearanceMenu  matlab.ui.container.Menu
        ExportDataMenu               matlab.ui.container.Menu
        matMenu                      matlab.ui.container.Menu
        txtMenu                      matlab.ui.container.Menu
        csvMenu                      matlab.ui.container.Menu
        TextArea_2                   matlab.ui.control.TextArea
        AnalysisSettingsLabel        matlab.ui.control.Label
        AnalysisSettingsPanel        matlab.ui.container.Panel
        CloseButton                  matlab.ui.control.Button
        FrequencyRangeHzEditField    matlab.ui.control.EditField
        FrequencyRangeHzfromtoEditFieldLabel  matlab.ui.control.Label
        EventSelectionEditField      matlab.ui.control.EditField
        TriggerNumberSelectionLabel  matlab.ui.control.Label
        DataSourceDropDown           matlab.ui.control.DropDown
        DataTypeDropDown_2Label      matlab.ui.control.Label
        ChannelDropDown              matlab.ui.control.DropDown
        ChannelDropDownLabel         matlab.ui.control.Label
        DataTypeDropDown             matlab.ui.control.DropDown
        AnalysisTypeDropDownLabel    matlab.ui.control.Label
        AnalysisDropDown             matlab.ui.control.DropDown
        AnalysisDropDownLabel        matlab.ui.control.Label
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Mainapp = []; % Description
        BandPower = []
        TwoORThreeD = "TwoD"; % standard 2d plots when possible
        TextArea = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            %% Initiate GUI 
            app.Mainapp = Mainapp;
            
            app = Utility_Change_Light_Dark_Mode(app,"EventSpectrum");

            movegui(app.EventStaticSpectrumWindowUIFigure, 'center');
            
            if isfield(app.Mainapp.Data,'EventRelatedData') && isfield(app.Mainapp.Data, 'PreprocessedEventRelatedData')
                app.DataSourceDropDown.Items = ["Raw Event Related Data","Preprocessed Event Related Data"];
                app.DataSourceDropDown.Value = "Raw Event Related Data";
            elseif isfield(app.Mainapp.Data, 'EventRelatedData') && ~isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                app.DataSourceDropDown.Items = "Raw Event Related Data";
            elseif ~isfield(app.Mainapp.Data, 'EventRelatedData') && isfield(app.Mainapp.Data, 'PreprocessedEventRelatedData')
                app.DataSourceDropDown.Items = "Preprocessed Event Related Data";
            end

            if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                app.EventSelectionEditField.Value = strcat('1:',num2str(size(app.Mainapp.Data.EventRelatedData,2)));
            else
                app.EventSelectionEditField.Value = strcat('1:',num2str(size(app.Mainapp.Data.PreprocessedEventRelatedData,2)));
            end

            % Generate Channel nr to be shown based on NRChannel saved
            
            AllChannels = {};
            if isfield(app.Mainapp.Data,'Raw')
                N = size(app.Mainapp.Data.Raw,1);  
            elseif isfield(app.Mainapp.Data,'Preprocessed')
                N = size(app.Mainapp.Data.Preprocessed,1);  
            end
            
            if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 1
                TempActiveChannel = (str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)*str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)+1)-sort(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
                for i = 1:N
                    AllChannels{i} = num2str(TempActiveChannel(i));
                end
            else
                for i = 1:N
                    AllChannels{i} = num2str(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel(i));
                end
            end

            app.ChannelDropDown.Items = AllChannels;

            SelectedChannel = str2double(app.ChannelDropDown.Value);              

            if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 1
                TempActiveChannel = (str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)*str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)+1)-sort(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
                [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(TempActiveChannel,SelectedChannel,'MainWindow');
            else
                [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,SelectedChannel,'MainWindow');
            end

            SelectedEvents = eval(app.EventSelectionEditField.Value);

            %% Compute and Plot Static Spectrum
            
            [app.Mainapp.CurrentPlotData] = Event_Analyse_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,SelectedEvents);
            
            [~,app.BandPower,~] = Event_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'Just Frequency Bands',app.TwoORThreeD,app.Mainapp.CurrentPlotData,SelectedEvents,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance);
            
            app.ChannelDropDown.Enable = "on";
            app.DataTypeDropDown.Enable = "on";
            app.FrequencyRangeHzEditField.Enable = "on";
            app.DataSourceDropDown.Enable = "on";
            app.AnalysisDropDown.Enable = "on";

            app.UIAxes.Color = app.Mainapp.PlotAppearance.SpectrumWindow.Data.SpectrumBackgroundColor;
            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeFontSize;
            
            app = Utility_Change_Light_Dark_Mode(app,"EventSpectrum");
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.Mainapp.EventLFPSSP = [];

            if ~isempty(app.Mainapp.ProbeViewWindowHandle) % delete option from probe view 
                for i = 1:length(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                    if strcmp(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Event Static Spectrum Window')
                        app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items(i) = [];
                        break;
                    end
                end
            else % if no probe view available
 
            end

            delete(app);
        end

        % Value changed function: DataTypeDropDown
        function DataTypeDropDownValueChanged(app, event)
            if strcmp(app.DataTypeDropDown.Value,"Mean over all Channel")
                app.ChannelDropDown.Enable = "off";
            else
                app.ChannelDropDown.Enable = "on";
                set(app.UIAxes, 'YDir', 'normal');
            end

            SelectedChannel = str2double(app.ChannelDropDown.Value);                                        
            if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 1
                TempActiveChannel = (str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)*str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)+1)-sort(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
                [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(TempActiveChannel,SelectedChannel,'MainWindow');
            else
                [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,SelectedChannel,'MainWindow');
            end
            SelectedEvents = eval(app.EventSelectionEditField.Value);
            
            [app.Mainapp.CurrentPlotData] = Event_Analyse_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,SelectedEvents);
        end

        % Value changed function: ChannelDropDown
        function ChannelDropDownValueChanged(app, event)
            SelectedChannel = str2double(app.ChannelDropDown.Value);                                        
            if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 1
                TempActiveChannel = (str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)*str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)+1)-sort(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
                [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(TempActiveChannel,SelectedChannel,'MainWindow');
            else
                [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,SelectedChannel,'MainWindow');
            end
            SelectedEvents = eval(app.EventSelectionEditField.Value);

            [app.Mainapp.CurrentPlotData] = Event_Analyse_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,SelectedEvents);
        end

        % Value changed function: DataSourceDropDown
        function DataSourceDropDownValueChanged(app, event)
            app.UIAxes_2.NextPlot = "replace";
            cla(app.UIAxes_2)
            app.UIAxes_2.NextPlot = "add";
            
            app.UIAxes.Color = app.Mainapp.PlotAppearance.SpectrumWindow.Data.SpectrumBackgroundColor;
            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeFontSize;
            
            if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                app.EventSelectionEditField.Value = strcat('1:',num2str(size(app.Mainapp.Data.EventRelatedData,2)));
            else
                app.EventSelectionEditField.Value = strcat('1:',num2str(size(app.Mainapp.Data.PreprocessedEventRelatedData,2)));
            end

            
            AllChannels = {};
            if isfield(app.Mainapp.Data,'Raw')
                N = size(app.Mainapp.Data.Raw,1);  
            elseif isfield(app.Mainapp.Data,'Preprocessed')
                N = size(app.Mainapp.Data.Preprocessed,1);  
            end
            
            if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 1
                TempActiveChannel = (str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)*str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)+1)-sort(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
                for i = 1:N
                    AllChannels{i} = num2str(TempActiveChannel(i));
                end
            else
                for i = 1:N
                    AllChannels{i} = num2str(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel(i));
                end
            end

            app.ChannelDropDown.Items = AllChannels;

            SelectedChannel = str2double(app.ChannelDropDown.Value);                                        
            if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 1
                TempActiveChannel = (str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)*str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)+1)-sort(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
                [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(TempActiveChannel,SelectedChannel,'MainWindow');
            else
                [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,SelectedChannel,'MainWindow');
            end
            SelectedEvents = eval(app.EventSelectionEditField.Value);

            if strcmp(app.AnalysisDropDown.Value,"Band Power Individual Channel ")
                app.ChannelDropDown.Enable = "on";
                app.DataTypeDropDown.Enable = "on";

                [app.Mainapp.CurrentPlotData] = Event_Analyse_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,SelectedEvents);

                [~,app.BandPower,~] = Event_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'Just Frequency Bands',app.TwoORThreeD,app.Mainapp.CurrentPlotData,SelectedEvents,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance);
            
            elseif strcmp(app.AnalysisDropDown.Value,"Band Power over Depth")
                app.ChannelDropDown.Enable = "off";
                app.DataTypeDropDown.Enable = "off";

                SelectedEvents = eval(app.EventSelectionEditField.Value);

                [~,app.BandPower,~] = Event_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'All',app.TwoORThreeD,app.Mainapp.CurrentPlotData,SelectedEvents,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance);
                
                cb = colorbar(app.UIAxes);
                cb.Color = 'k';              % Sets tick mark and label color to black
                cb.Label.String = "Power [dB]";
                cb.Label.Rotation = 270;
                %cb.FontSize =  app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeFontSize;
                cb.Label.Color = 'k';        % Sets the color of the label text
            end
        end

        % Menu selected function: PNGMenu
        function PNGMenuSelected(app, event)
            [file, path] = uiputfile('*.png', 'Save as');

            exportapp(app.EventStaticSpectrumWindowUIFigure,fullfile(path,file)) 
        end

        % Menu selected function: JPGMenu
        function JPGMenuSelected(app, event)
            [file, path] = uiputfile('*.jpg', 'Save as');

            exportapp(app.EventStaticSpectrumWindowUIFigure,fullfile(path,file)) 
        end

        % Value changed function: AnalysisDropDown
        function AnalysisDropDownValueChanged(app, event)
            PowerDepth_handles = findobj(app.UIAxes, 'Tag', 'PowerDepth');
            delete(PowerDepth_handles(1:end));

            PWelch_handles = findobj(app.UIAxes, 'Tag', 'Pwelch');
            delete(PWelch_handles(1:end));

            app.UIAxes.Color = app.Mainapp.PlotAppearance.SpectrumWindow.Data.SpectrumBackgroundColor;
            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeFontSize;

            if strcmp(app.AnalysisDropDown.Value,"Band Power Individual Channel ")
                app.ChannelDropDown.Enable = "on";
                app.DataTypeDropDown.Enable = "on";
                app.FrequencyRangeHzEditField.Enable = "on";

                SelectedChannel = str2double(app.ChannelDropDown.Value);                                        
                if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 1
                    TempActiveChannel = (str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)*str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)+1)-sort(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
                    [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(TempActiveChannel,SelectedChannel,'MainWindow');
                else
                    [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,SelectedChannel,'MainWindow');
                end
                SelectedEvents = eval(app.EventSelectionEditField.Value);

                set(app.UIAxes, 'YDir', 'normal');
                cb = colorbar(app.UIAxes);   % Create a colorbar (if it exists)
                if ~isempty(cb)
                    delete(cb);                  % Delete the colorbar
                end

                [app.Mainapp.CurrentPlotData] = Event_Analyse_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,SelectedEvents);

            elseif strcmp(app.AnalysisDropDown.Value,"Band Power over Depth")
                app.ChannelDropDown.Enable = "off";
                app.DataTypeDropDown.Enable = "off";
                app.FrequencyRangeHzEditField.Enable = "on";

                app.UIAxes.YScale = 'linear';
                
                SelectedEvents = eval(app.EventSelectionEditField.Value);

                [~,app.BandPower,~] = Event_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'All',app.TwoORThreeD,app.Mainapp.CurrentPlotData,SelectedEvents,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance);

                cb = colorbar(app.UIAxes);
                cb.Color = 'k';              % Sets tick mark and label color to black
                cb.Label.String = "Power [dB]";
                cb.Label.Rotation = 270;
                %cb.FontSize =  app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeFontSize;
                cb.Label.Color = 'k';        % Sets the color of the label text

            end
        end

        % Value changed function: FrequencyRangeHzEditField
        function FrequencyRangeHzEditFieldValueChanged(app, event)
            [app.FrequencyRangeHzEditField.Value] = Utility_SimpleCheckInputs(app.FrequencyRangeHzEditField.Value,"Two",'0,100',0,0);

            if strcmp(app.AnalysisDropDown.Value,"Band Power Individual Channel ")
                app.ChannelDropDown.Enable = "on";
                app.DataTypeDropDown.Enable = "on";
                app.FrequencyRangeHzEditField.Enable = "on";

                SelectedChannel = str2double(app.ChannelDropDown.Value);                                        
                if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 1
                    TempActiveChannel = (str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)*str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)+1)-sort(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
                    [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(TempActiveChannel,SelectedChannel,'MainWindow');
                else
                    [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,SelectedChannel,'MainWindow');
                end
                SelectedEvents = eval(app.EventSelectionEditField.Value);

                set(app.UIAxes, 'YDir', 'normal');

                [app.Mainapp.CurrentPlotData] = Event_Analyse_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,SelectedEvents);

            elseif strcmp(app.AnalysisDropDown.Value,"Band Power over Depth")
                app.ChannelDropDown.Enable = "off";
                app.DataTypeDropDown.Enable = "off";
                app.FrequencyRangeHzEditField.Enable = "on";
                
                SelectedEvents = eval(app.EventSelectionEditField.Value);

                [~,app.BandPower,~] = Event_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'All',app.TwoORThreeD,app.Mainapp.CurrentPlotData,SelectedEvents,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance);
                
                cb = colorbar(app.UIAxes);
                cb.Color = 'k';              % Sets tick mark and label color to black
                cb.Label.String = "Power [dB]";
                cb.Label.Rotation = 270;
                %cb.FontSize =  app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeFontSize;
                cb.Label.Color = 'k';        % Sets the color of the label text

            end

        end

        % Menu selected function: Show2DimensionalPlotsMenu
        function Show2DimensionalPlotsMenuSelected(app, event)
            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";
            
            app.UIAxes.Color = app.Mainapp.PlotAppearance.SpectrumWindow.Data.SpectrumBackgroundColor;
            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeFontSize;

            app.TwoORThreeD = "TwoD";

            if strcmp(app.AnalysisDropDown.Value,"Band Power Individual Channel ")
                app.ChannelDropDown.Enable = "on";
                app.DataTypeDropDown.Enable = "on";

                SelectedChannel = str2double(app.ChannelDropDown.Value);                                        
                if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 1
                    TempActiveChannel = (str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)*str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)+1)-sort(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
                    [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(TempActiveChannel,SelectedChannel,'MainWindow');
                else
                    [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,SelectedChannel,'MainWindow');
                end
                SelectedEvents = eval(app.EventSelectionEditField.Value);

                set(app.UIAxes, 'YDir', 'normal');

                [app.Mainapp.CurrentPlotData] = Event_Analyse_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,SelectedEvents);

            elseif strcmp(app.AnalysisDropDown.Value,"Band Power over Depth")
                app.ChannelDropDown.Enable = "off";
                app.DataTypeDropDown.Enable = "off";
                app.FrequencyRangeHzEditField.Enable = "on";
                
                SelectedEvents = eval(app.EventSelectionEditField.Value);

                [~,app.BandPower,~] = Event_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'All',app.TwoORThreeD,app.Mainapp.CurrentPlotData,SelectedEvents,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance);
            
            end
        end

        % Menu selected function: matMenu
        function matMenuSelected(app, event)
            TimeRangePlot = str2double(app.Mainapp.TimeRangeViewBox.Value(1:end-1));
            
            if strcmp(app.Mainapp.DropDown.Value,"Preprocessed")
                if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                else
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                end
            else
                StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
            end

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Static_Spectrum");
        end

        % Menu selected function: txtMenu
        function txtMenuSelected(app, event)
            TimeRangePlot = str2double(app.Mainapp.TimeRangeViewBox.Value(1:end-1));
            if strcmp(app.Mainapp.DropDown.Value,"Preprocessed")
                if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                else
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                end
            else
                StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
            end

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Static_Spectrum");
        end

        % Menu selected function: csvMenu
        function csvMenuSelected(app, event)
            TimeRangePlot = str2double(app.Mainapp.TimeRangeViewBox.Value(1:end-1));
            if strcmp(app.Mainapp.DropDown.Value,"Preprocessed")
                if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                else
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                end
            else
                StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
            end

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".csv",app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Static_Spectrum");
        end

        % Menu selected function: Show3DimensionalPlotsMenu
        function Show3DimensionalPlotsMenuSelected(app, event)
            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";
            
            app.UIAxes.Color = app.Mainapp.PlotAppearance.SpectrumWindow.Data.SpectrumBackgroundColor;
            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeFontSize;

            app.TwoORThreeD = "ThreeD";

            if strcmp(app.AnalysisDropDown.Value,"Band Power Individual Channel ")
                app.ChannelDropDown.Enable = "on";
                app.DataTypeDropDown.Enable = "on";

                SelectedChannel = str2double(app.ChannelDropDown.Value);                                        
                if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 1
                    TempActiveChannel = (str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)*str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)+1)-sort(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
                    [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(TempActiveChannel,SelectedChannel,'MainWindow');
                else
                    [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,SelectedChannel,'MainWindow');
                end
                SelectedEvents = eval(app.EventSelectionEditField.Value);

                set(app.UIAxes, 'YDir', 'normal');

                [app.Mainapp.CurrentPlotData] = Event_Analyse_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,SelectedEvents);

            elseif strcmp(app.AnalysisDropDown.Value,"Band Power over Depth")
                app.ChannelDropDown.Enable = "off";
                app.DataTypeDropDown.Enable = "off";
                app.FrequencyRangeHzEditField.Enable = "on";
                
                SelectedEvents = eval(app.EventSelectionEditField.Value);

                [~,app.BandPower,~] = Event_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'All',app.TwoORThreeD,app.Mainapp.CurrentPlotData,SelectedEvents,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance);
            end
        end

        % Menu selected function: FontSizeMenu
        function FontSizeMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'fontsize',app.Mainapp,"SpectrumFontSize");
            waitfor(h);
            
            if strcmp(app.AnalysisDropDown.Value,"Band Power Individual Channel ")
                
                SelectedChannel = str2double(app.ChannelDropDown.Value);                                        
                if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 1
                    TempActiveChannel = (str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)*str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)+1)-sort(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
                    [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(TempActiveChannel,SelectedChannel,'MainWindow');
                else
                    [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,SelectedChannel,'MainWindow');
                end
                SelectedEvents = eval(app.EventSelectionEditField.Value);
   
                [app.Mainapp.CurrentPlotData] = Event_Analyse_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,SelectedEvents);
            end

            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeFontSize;
        end

        % Menu selected function: LineWidthDataMenu
        function LineWidthDataMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'linewidth',app.Mainapp,"SpectrumLineWidth");
            waitfor(h);
            
            if strcmp(app.AnalysisDropDown.Value,"Band Power Individual Channel ")
                
                SelectedChannel = str2double(app.ChannelDropDown.Value);                                        
                if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 1
                    TempActiveChannel = (str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)*str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)+1)-sort(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
                    [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(TempActiveChannel,SelectedChannel,'MainWindow');
                else
                    [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,SelectedChannel,'MainWindow');
                end
                SelectedEvents = eval(app.EventSelectionEditField.Value);
   
                [app.Mainapp.CurrentPlotData] = Event_Analyse_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,SelectedEvents);
            end
          
        end

        % Menu selected function: ColorDataMenu
        function ColorDataMenuSelected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 

            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3

                app.Mainapp.PlotAppearance.SpectrumWindow.Data.SpectrumColor = selectedColor;

                if strcmp(app.AnalysisDropDown.Value,"Band Power Individual Channel ")
                    
                    SelectedChannel = str2double(app.ChannelDropDown.Value);                                        
                    if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 1
                        TempActiveChannel = (str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)*str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)+1)-sort(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
                        [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(TempActiveChannel,SelectedChannel,'MainWindow');
                    else
                        [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,SelectedChannel,'MainWindow');
                    end
                    SelectedEvents = eval(app.EventSelectionEditField.Value);
       
                    [app.Mainapp.CurrentPlotData] = Event_Analyse_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,SelectedEvents);
                end
            end
        end

        % Menu selected function: BackgorundColorMenu
        function BackgorundColorMenuSelected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 

            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.Mainapp.PlotAppearance.SpectrumWindow.Data.SpectrumBackgroundColor = selectedColor;
                app.UIAxes.Color = app.Mainapp.PlotAppearance.SpectrumWindow.Data.SpectrumBackgroundColor;
                app.UIAxes_2.Color = app.Mainapp.PlotAppearance.SpectrumWindow.Data.SpectrumBackgroundColor;
            end
        end

        % Menu selected function: XLabelMenu
        function XLabelMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'xlabel',app.Mainapp,"SpectrumWindowDataXlabel");
            waitfor(h);
            
            if strcmp(app.AnalysisDropDown.Value,"Band Power Individual Channel ")
                
                SelectedChannel = str2double(app.ChannelDropDown.Value);                                        
                if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 1
                    TempActiveChannel = (str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)*str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)+1)-sort(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
                    [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(TempActiveChannel,SelectedChannel,'MainWindow');
                else
                    [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,SelectedChannel,'MainWindow');
                end
                SelectedEvents = eval(app.EventSelectionEditField.Value);
   
                [app.Mainapp.CurrentPlotData] = Event_Analyse_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,SelectedEvents);
            end
        end

        % Menu selected function: YLabelMenu
        function YLabelMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'ylabel',app.Mainapp,"SpectrumWindowDataYlabel");
            waitfor(h);
            
            if strcmp(app.AnalysisDropDown.Value,"Band Power Individual Channel ")
                
                SelectedChannel = str2double(app.ChannelDropDown.Value);                                        
                if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 1
                    TempActiveChannel = (str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)*str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)+1)-sort(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
                    [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(TempActiveChannel,SelectedChannel,'MainWindow');
                else
                    [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,SelectedChannel,'MainWindow');
                end
                SelectedEvents = eval(app.EventSelectionEditField.Value);
   
                [app.Mainapp.CurrentPlotData] = Event_Analyse_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,SelectedEvents);
            end
        end

        % Menu selected function: RESETMenu
        function RESETMenuSelected(app, event)
        app.UIAxes.NextPlot = "replace"; 
        plot(app.UIAxes,0,0);
        app.UIAxes.NextPlot = "add"; 

        [app.Mainapp.PlotAppearance] = Organize_Set_Standard_PlotAppearance("SpectrumPlot",app.Mainapp.PlotAppearance);

        if strcmp(app.AnalysisDropDown.Value,"Band Power Individual Channel ")   
            SelectedChannel = str2double(app.ChannelDropDown.Value);                                        
            if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 1
                TempActiveChannel = (str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)*str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)+1)-sort(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
                [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(TempActiveChannel,SelectedChannel,'MainWindow');
            else
                [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,SelectedChannel,'MainWindow');
            end
            SelectedEvents = eval(app.EventSelectionEditField.Value);
       
            [app.Mainapp.CurrentPlotData] = Event_Analyse_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,SelectedEvents);
    
        elseif strcmp(app.AnalysisDropDown.Value,"Band Power over Depth")
            SelectedEvents = eval(app.EventSelectionEditField.Value);
            [~,app.BandPower,~] = Event_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'All',app.TwoORThreeD,app.Mainapp.CurrentPlotData,SelectedEvents,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance);
        end 
        end

        % Menu selected function: SaveCostumeAppearanceMenu
        function SaveCostumeAppearanceMenuSelected(app, event)
            app.Mainapp.PlotAppearance = Utility_Save_Load_Delete_Plot_Appearance(app.Mainapp.PlotAppearance,app.Mainapp.executableFolder,"Save");
        end

        % Menu selected function: DeleteCostumeAppearanceMenu
        function DeleteCostumeAppearanceMenuSelected(app, event)
            app.Mainapp.PlotAppearance = Utility_Save_Load_Delete_Plot_Appearance(app.Mainapp.PlotAppearance,app.Mainapp.executableFolder,"Delete");
        end

        % Value changed function: EventSelectionEditField
        function EventSelectionEditFieldValueChanged(app, event)
            cla(app.UIAxes_2);

            if contains(app.EventSelectionEditField.Value,',')
                if ~contains(app.EventSelectionEditField.Value,'[') || ~contains(app.EventSelectionEditField.Value,']')
                    app.EventSelectionEditField.Value = strcat('[',app.EventSelectionEditField.Value,']');
                end
            end
            
            if strcmp(app.DataSourceDropDown.Value,'Raw Event Related Data')
                AlleventNr = size(app.Mainapp.Data.EventRelatedData,2);
                try
                    EventNr = eval(app.EventSelectionEditField.Value);
                catch
                    EventNr = 1:AlleventNr;
                    app.EventSelectionEditField.Value = strcat(num2str(min(EventNr)),':',num2str(max(EventNr)));
                end
    
                if length(EventNr)>AlleventNr || sum(EventNr>AlleventNr) ~= 0 || isempty(EventNr)
                    msgbox("Error: Trigger range exceeds existing number of triggers! Plotting all trigger.")
                    EventNr = 1:AlleventNr;
                    app.EventSelectionEditField.Value = strcat(num2str(min(EventNr)),':',num2str(max(EventNr)));
                end
            else
                AlleventNr = size(app.Mainapp.Data.PreprocessedEventRelatedData,2);
                try
                    EventNr = eval(app.EventSelectionEditField.Value);
                catch
                    EventNr = 1:AlleventNr;
                    app.EventSelectionEditField.Value = strcat(num2str(min(EventNr)),':',num2str(max(EventNr)));
                end
    
                if length(EventNr)>AlleventNr || sum(EventNr>AlleventNr) ~= 0 || isempty(EventNr)
                    msgbox("Error: Trigger range exceeds existing number of triggers! Plotting all trigger.")
                    EventNr = 1:AlleventNr;
                    app.EventSelectionEditField.Value = strcat(num2str(min(EventNr)),':',num2str(max(EventNr)));
                end
            end
            
            if strcmp(app.AnalysisDropDown.Value,"Band Power Individual Channel ")   
                SelectedChannel = str2double(app.ChannelDropDown.Value);                                        
                if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 1
                    TempActiveChannel = (str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)*str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)+1)-sort(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
                    [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(TempActiveChannel,SelectedChannel,'MainWindow');
                else
                    [SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,SelectedChannel,'MainWindow');
                end
                SelectedEvents = eval(app.EventSelectionEditField.Value);
           
                [app.Mainapp.CurrentPlotData] = Event_Analyse_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,SelectedEvents);
                [~,app.BandPower,~] = Event_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'Just Frequency Bands',app.TwoORThreeD,app.Mainapp.CurrentPlotData,SelectedEvents,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance);
            elseif strcmp(app.AnalysisDropDown.Value,"Band Power over Depth")
                SelectedEvents = eval(app.EventSelectionEditField.Value);
                [~,app.BandPower,~] = Event_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'All',app.TwoORThreeD,app.Mainapp.CurrentPlotData,SelectedEvents,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance);
                
                cb = colorbar(app.UIAxes);
                cb.Color = 'k';              % Sets tick mark and label color to black
                cb.Label.String = "Power [dB]";
                cb.Label.Rotation = 270;
                %cb.FontSize =  app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeFontSize;
                cb.Label.Color = 'k';        % Sets the color of the label text

            end 
            
            
        end

        % Close request function: EventStaticSpectrumWindowUIFigure
        function EventStaticSpectrumWindowUIFigureCloseRequest(app, event)
           app.Mainapp.EventLFPSSP = [];

            if ~isempty(app.Mainapp.ProbeViewWindowHandle) % delete option from probe view 
                for i = 1:length(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                    if strcmp(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Event Static Spectrum Window')
                        app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items(i) = [];
                        break;
                    end
                end
            else % if no probe view available
 
            end

            delete(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EventStaticSpectrumWindowUIFigure = uifigure('Visible', 'off');
            app.EventStaticSpectrumWindowUIFigure.Name = 'Event Static Spectrum Window';
            app.EventStaticSpectrumWindowUIFigure.Icon = 'Logo.png';
            app.EventStaticSpectrumWindowUIFigure.CloseRequestFcn = createCallbackFcn(app, @EventStaticSpectrumWindowUIFigureCloseRequest, true);
            app.SnapshotMenu = uimenu(app.EventStaticSpectrumWindowUIFigure);
            app.SnapshotMenu.Text = '   Snapshot   ';
            app.SaveSnapshotofWindowMenu = uimenu(app.SnapshotMenu);
            app.SaveSnapshotofWindowMenu.Text = 'Save Snapshot of Window';
            app.PNGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.PNGMenu.MenuSelectedFcn = createCallbackFcn(app, @PNGMenuSelected, true);
            app.PNGMenu.Text = 'PNG';
            app.JPGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.JPGMenu.MenuSelectedFcn = createCallbackFcn(app, @JPGMenuSelected, true);
            app.JPGMenu.Text = 'JPG';
            app.PlotMenu_2 = uimenu(app.EventStaticSpectrumWindowUIFigure);
            app.PlotMenu_2.Text = '   Plot   ';
            app.Show2DimensionalPlotsMenu = uimenu(app.PlotMenu_2);
            app.Show2DimensionalPlotsMenu.MenuSelectedFcn = createCallbackFcn(app, @Show2DimensionalPlotsMenuSelected, true);
            app.Show2DimensionalPlotsMenu.Text = 'Show 2 Dimensional Plots';
            app.Show3DimensionalPlotsMenu = uimenu(app.PlotMenu_2);
            app.Show3DimensionalPlotsMenu.MenuSelectedFcn = createCallbackFcn(app, @Show3DimensionalPlotsMenuSelected, true);
            app.Show3DimensionalPlotsMenu.Text = 'Show 3 Dimensional Plots';
            app.ChangePlotPropertiesMenu = uimenu(app.PlotMenu_2);
            app.ChangePlotPropertiesMenu.Text = 'Change Plot Properties';
            app.DataPlotMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.DataPlotMenu.Text = 'Data Plot';
            app.FontSizeMenu = uimenu(app.DataPlotMenu);
            app.FontSizeMenu.MenuSelectedFcn = createCallbackFcn(app, @FontSizeMenuSelected, true);
            app.FontSizeMenu.Text = 'FontSize';
            app.LineWidthDataMenu = uimenu(app.DataPlotMenu);
            app.LineWidthDataMenu.MenuSelectedFcn = createCallbackFcn(app, @LineWidthDataMenuSelected, true);
            app.LineWidthDataMenu.Text = 'LineWidth Data';
            app.ColorDataMenu = uimenu(app.DataPlotMenu);
            app.ColorDataMenu.MenuSelectedFcn = createCallbackFcn(app, @ColorDataMenuSelected, true);
            app.ColorDataMenu.Text = 'Color Data';
            app.BackgorundColorMenu = uimenu(app.DataPlotMenu);
            app.BackgorundColorMenu.MenuSelectedFcn = createCallbackFcn(app, @BackgorundColorMenuSelected, true);
            app.BackgorundColorMenu.Text = 'Backgorund Color';
            app.XLabelMenu = uimenu(app.DataPlotMenu);
            app.XLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @XLabelMenuSelected, true);
            app.XLabelMenu.Text = 'XLabel';
            app.YLabelMenu = uimenu(app.DataPlotMenu);
            app.YLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @YLabelMenuSelected, true);
            app.YLabelMenu.Text = 'YLabel';
            app.RESETMenu = uimenu(app.DataPlotMenu);
            app.RESETMenu.MenuSelectedFcn = createCallbackFcn(app, @RESETMenuSelected, true);
            app.RESETMenu.Text = 'RESET';
            app.SaveCostumeAppearanceMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.SaveCostumeAppearanceMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveCostumeAppearanceMenuSelected, true);
            app.SaveCostumeAppearanceMenu.Text = 'Save Costume Appearance';
            app.DeleteCostumeAppearanceMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.DeleteCostumeAppearanceMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteCostumeAppearanceMenuSelected, true);
            app.DeleteCostumeAppearanceMenu.Text = 'Delete Costume Appearance';
            app.ExportDataMenu = uimenu(app.EventStaticSpectrumWindowUIFigure);
            app.ExportDataMenu.Text = '   Export Data   ';
            app.matMenu = uimenu(app.ExportDataMenu);
            app.matMenu.MenuSelectedFcn = createCallbackFcn(app, @matMenuSelected, true);
            app.matMenu.Text = '.mat';
            app.txtMenu = uimenu(app.ExportDataMenu);
            app.txtMenu.MenuSelectedFcn = createCallbackFcn(app, @txtMenuSelected, true);
            app.txtMenu.Text = '.txt';
            app.csvMenu = uimenu(app.ExportDataMenu);
            app.csvMenu.MenuSelectedFcn = createCallbackFcn(app, @csvMenuSelected, true);
            app.csvMenu.Text = '.csv';
            app.UIAxes = uiaxes(app.EventStaticSpectrumWindowUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.EventStaticSpectrumWindowUIFigure);
            xlabel(app.UIAxes_2, 'X')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.YTick = [];
            app.AnalysisSettingsPanel = uipanel(app.EventStaticSpectrumWindowUIFigure);
            app.AnalysisSettingsPanel.Title = 'Analysis Settings';
            app.AnalysisDropDownLabel = uilabel(app.AnalysisSettingsPanel);
            app.AnalysisDropDownLabel.Text = 'Analysis';
            app.AnalysisDropDown = uidropdown(app.AnalysisSettingsPanel);
            app.AnalysisDropDown.Items = {'Band Power Individual Channel ', 'Band Power over Depth'};
            app.AnalysisDropDown.ValueChangedFcn = createCallbackFcn(app, @AnalysisDropDownValueChanged, true);
            app.AnalysisDropDown.Value = 'Band Power Individual Channel ';
            app.AnalysisTypeDropDownLabel = uilabel(app.AnalysisSettingsPanel);
            app.AnalysisTypeDropDownLabel.Text = 'Analysis Type';
            app.DataTypeDropDown = uidropdown(app.AnalysisSettingsPanel);
            app.DataTypeDropDown.Items = {'Channel Individually', 'Mean over all Channel'};
            app.DataTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DataTypeDropDownValueChanged, true);
            app.DataTypeDropDown.Value = 'Channel Individually';
            app.ChannelDropDownLabel = uilabel(app.AnalysisSettingsPanel);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.AnalysisSettingsPanel);
            app.ChannelDropDown.Items = {};
            app.ChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelDropDownValueChanged, true);
            app.ChannelDropDown.Value = {};
            app.DataTypeDropDown_2Label = uilabel(app.AnalysisSettingsPanel);
            app.DataTypeDropDown_2Label.Text = 'Data Type';
            app.DataSourceDropDown = uidropdown(app.AnalysisSettingsPanel);
            app.DataSourceDropDown.Items = {'Raw Event Related Data', 'Preprocessed Data'};
            app.DataSourceDropDown.ValueChangedFcn = createCallbackFcn(app, @DataSourceDropDownValueChanged, true);
            app.DataSourceDropDown.Value = 'Raw Event Related Data';
            app.TriggerNumberSelectionLabel = uilabel(app.AnalysisSettingsPanel);
            app.TriggerNumberSelectionLabel.Text = {'Trigger Number '; 'Selection (Matlab Expressions)'};
            app.EventSelectionEditField = uieditfield(app.AnalysisSettingsPanel, 'text');
            app.EventSelectionEditField.ValueChangedFcn = createCallbackFcn(app, @EventSelectionEditFieldValueChanged, true);
            app.FrequencyRangeHzfromtoEditFieldLabel = uilabel(app.AnalysisSettingsPanel);
            app.FrequencyRangeHzfromtoEditFieldLabel.Text = {'Frequency'; 'Range [Hz]'; '(from,to)'};
            app.FrequencyRangeHzEditField = uieditfield(app.AnalysisSettingsPanel, 'text');
            app.FrequencyRangeHzEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyRangeHzEditFieldValueChanged, true);
            app.FrequencyRangeHzEditField.Enable = 'off';
            app.FrequencyRangeHzEditField.Value = '0,100';
            app.CloseButton = uibutton(app.AnalysisSettingsPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.AnalysisSettingsLabel = uilabel(app.EventStaticSpectrumWindowUIFigure);
            app.AnalysisSettingsLabel.Text = 'Analysis Settings';
            app.TextArea_2 = uitextarea(app.EventStaticSpectrumWindowUIFigure);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Enable = 'off';
            app.TextArea_2.Visible = 'off';
            % Show the figure after all components are created
            app.EventStaticSpectrumWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Event_Static_Power_Spectrum_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.EventStaticSpectrumWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.EventStaticSpectrumWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EventStaticSpectrumWindowUIFigure)
        end
    end
end