classdef Clean_Events < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CleanEventTriggerUIFigure  matlab.ui.Figure
        CloseButton                matlab.ui.control.Button
        LeavemarkedeventsandcontinueButton  matlab.ui.control.Button
        DeletemarkedeventsandcontinueButton  matlab.ui.control.Button
        TextArea                   matlab.ui.control.TextArea
        Image                      matlab.ui.control.Image
        NeuromodLabel              matlab.ui.control.Label
        CleanEventTTLTriggerLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        EventsToClean % Description
        Data
        InputChannelType
        EventsAppWIndow = []
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Data, EventsToClean, InputChannelType)
        % Moce this window in the middle of thescreen
        movegui(app.CleanEventTriggerUIFigure, 'center');
        
        app.EventsToClean = EventsToClean;
        app.Data = Data;
        app.InputChannelType = InputChannelType;

        for i = 1:length(app.Data.Events)
            if ~isempty(app.EventsToClean{i})
                if strcmp(app.TextArea.Value{1},'')
                    app.TextArea.Value = [strcat(num2str(length(app.EventsToClean{i}))," Events/TTLs found for event ",InputChannelType," number ",num2str(i)," that are closer to other event triggers than the mean distance between event triggers minus their standard deviation is. If the time inbetween event triggers is supposed to be (roughly) the same, there are prb noise spikes in your event trigger data!")];
                    app.TextArea.Value = [app.TextArea.Value;"These are the time points of the suspicious events. Please use the 'Plot InputChannel' window to plot the event channel and look at the signal at those time points. DO NOT plot the downsampled signal! (change sample rate to your native sample rate!!!!)"];
                    app.TextArea.Value = [app.TextArea.Value;""];
                    app.TextArea.Value = [app.TextArea.Value;"In Seconds:"];
                    app.TextArea.Value = [app.TextArea.Value;num2str(Data.Events{i}(app.EventsToClean{i})/Data.Info.NativeSamplingRate)];
                
                else
                    app.TextArea.Value = [app.TextArea.Value;"";strcat(num2str(length(app.EventsToClean{i}))," Events/TTLs found for event ",InputChannelType," number ",num2str(i)," that are closer to other event triggers than the mean distance between event triggers minus their standard deviation is. If the time inbetween event triggers is supposed to be (roughly) the same, there are prb noise spikes in your event trigger data!")];
                    app.TextArea.Value = [app.TextArea.Value;"These are the time points of the suspicious events. Please use the 'Plot InputChannel' window to plot the event channel and look at the signal at those time points. DO NOT plot the downsampled signal! (change sample rate to your native sample rate!!!!)"];
                    app.TextArea.Value = [app.TextArea.Value;""];
                    app.TextArea.Value = [app.TextArea.Value;"In Seconds:"];
                    app.TextArea.Value = [app.TextArea.Value;num2str(Data.Events{i}(app.EventsToClean{i})/Data.Info.NativeSamplingRate)];
                end
            end
        end
        
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app)
        end

        % Close request function: CleanEventTriggerUIFigure
        function CleanEventTriggerUIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: LeavemarkedeventsandcontinueButton
        function LeavemarkedeventsandcontinueButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: DeletemarkedeventsandcontinueButton
        function DeletemarkedeventsandcontinueButtonPushed(app, event)
        IndiceToDelete = [];
        for i = 1:length(app.EventsToClean)
            if ~isempty(app.EventsToClean{i})
                app.Data.Events{i}(app.EventsToClean{i}) = [];
                if isempty(app.Data.Events{i})
                    IndiceToDelete = [IndiceToDelete,i];
                end
            end
        end
        
        if ~isempty(IndiceToDelete)
            app.Data.Events(IndiceToDelete) = [];
        end
        
        %Resume execution to return the output
        uiresume(app.CleanEventTriggerUIFigure);   
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CleanEventTriggerUIFigure = uifigure('Visible', 'off');
            app.CleanEventTriggerUIFigure.Name = 'Clean Event Trigger';
            app.CleanEventTriggerUIFigure.Icon = 'Logo.png';
            app.CleanEventTriggerUIFigure.CloseRequestFcn = createCallbackFcn(app, @CleanEventTriggerUIFigureCloseRequest, true);
            app.CleanEventTTLTriggerLabel = uilabel(app.CleanEventTriggerUIFigure);
            app.CleanEventTTLTriggerLabel.Text = 'Clean Event/TTL Trigger';
            app.NeuromodLabel = uilabel(app.CleanEventTriggerUIFigure);
            app.NeuromodLabel.Text = 'Neuromod ';
            app.Image = uiimage(app.CleanEventTriggerUIFigure);
            app.Image.ImageSource = 'Logo.png';
            app.TextArea = uitextarea(app.CleanEventTriggerUIFigure);
            app.DeletemarkedeventsandcontinueButton = uibutton(app.CleanEventTriggerUIFigure, 'push');
            app.DeletemarkedeventsandcontinueButton.ButtonPushedFcn = createCallbackFcn(app, @DeletemarkedeventsandcontinueButtonPushed, true);
            app.DeletemarkedeventsandcontinueButton.Text = {'Delete marked events'; 'and continue'};
            app.LeavemarkedeventsandcontinueButton = uibutton(app.CleanEventTriggerUIFigure, 'push');
            app.LeavemarkedeventsandcontinueButton.ButtonPushedFcn = createCallbackFcn(app, @LeavemarkedeventsandcontinueButtonPushed, true);
            app.LeavemarkedeventsandcontinueButton.Text = {'Leave marked events'; 'and continue'};
            app.CloseButton = uibutton(app.CleanEventTriggerUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.CleanEventTriggerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Clean_Events(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.CleanEventTriggerUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.CleanEventTriggerUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CleanEventTriggerUIFigure)
        end
    end
end