classdef Extract_Events_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ExtractEventsWindowUIFigure     matlab.ui.Figure
        ExtractEventRelatedDataPanel    matlab.ui.container.Panel
        ExtractEventRelatedDataButton   matlab.ui.control.Button
        Panel_2                         matlab.ui.container.Panel
        TextArea_3                      matlab.ui.control.TextArea
        EventChanneltoUseDropDown       matlab.ui.control.DropDown
        EventChanneltoUseDropDownLabel  matlab.ui.control.Label
        DatatouseDropDown               matlab.ui.control.DropDown
        DatatouseDropDownLabel          matlab.ui.control.Label
        TimeWindowaftereventssEditField  matlab.ui.control.EditField
        TimeWindowaftereventssEditFieldLabel  matlab.ui.control.Label
        TimeWindowbeforeeventssEditField  matlab.ui.control.EditField
        TimeWindowbeforeeventssEditFieldLabel  matlab.ui.control.Label
        CloseButton                     matlab.ui.control.Button
        NeuromodToolboxLabel            matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        ExtractEventsWindowLabel        matlab.ui.control.Label
        ExtractEventsfromRawEventChannelPanel  matlab.ui.container.Panel
        ShowInputChannelPlotButton      matlab.ui.control.Button
        SetfoldermanuallyButton         matlab.ui.control.Button
        StartEventExtractionButton      matlab.ui.control.Button
        FilesinPathLabel                matlab.ui.control.Label
        TextArea_2                      matlab.ui.control.TextArea
        FilePathLabel                   matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        Panel                           matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        RecordingSystem                 matlab.ui.control.EditField
        AnalogThresholdVEditField       matlab.ui.control.EditField
        TriggerRangeVLabel              matlab.ui.control.Label
        NrInputChinfolderEditField      matlab.ui.control.EditField
        NrInputChinfolderEditFieldLabel  matlab.ui.control.Label
        InputChannelSelectionEditField  matlab.ui.control.EditField
        InputChannelSelectionEditFieldLabel  matlab.ui.control.Label
        RecordingSystemLabel            matlab.ui.control.Label
        SelectFileTypeLabel             matlab.ui.control.Label
        FileTypeDropDown                matlab.ui.control.DropDown
    end

    
    properties (Access = public)
        Mainapp = [] % Description
        Path = [];
        EventInfo = [];
        RHDAllChannelData = [];
        ExtractedRHDEventsFlag = 0;
        FilesinPath = [];
        TimearoundEvent = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            % Moce this window in the middle of thescreen
            movegui(app.ExtractEventsWindowUIFigure, 'center');

            app.Mainapp = Mainapp;

            app.Path = app.Mainapp.Data.Info.Data_Path;

            %% Initiate Event Extraction Panel

            app.TextArea.Value = app.Path;
            pause(0.2);

            if strcmp(app.Mainapp.Data.Info.RecordingType,"IntanDat")            
                app.RecordingSystem.Value = "Intan .dat files";
            elseif strcmp(app.Mainapp.Data.Info.RecordingType,"IntanRHD")            
                %% Set up whats shown in the GUI
                app.RecordingSystem.Value = "Intan .rhd files";
            elseif strcmp(app.Mainapp.Data.Info.RecordingType,"Neuralynx") %%%% Not implemented yet
                % Setup GUI fields when data was recorded with Neuralynx
                app.FileTypeDropDown.Enable = "off";
                app.FileTypeDropDown.Items = {'Events.nev'};
                app.AnalogThresholdVEditField.Value = "";
                app.AnalogThresholdVEditField.Enable = "off";
                app.RecordingSystem.Value = "Neuralynx files";
            elseif strcmp(app.Mainapp.Data.Info.RecordingType,"Open Ephys") 
                app.RecordingSystem.Value = "Open Ephys";
            end

            %% Function to determine which event channel area vailable in folder based on names of folder components

            [app.EventInfo,FileEndingsExist,FilesIndex,DatFilePaths,app.FilesinPath] = Extract_Events_Module_Determine_Available_EventChannel(app.Mainapp.Data,app.Path,app.TextArea,app.TextArea_2,app.FileTypeDropDown.Value);

            if isempty(FileEndingsExist)
                FileEndingsExist = 1;
            end

            %% Set up whats shown in the GUI
            % Initialize the first things shown in GUI: Digital Channel

            % Analyse how many input event channel exist
            if strcmp(app.Mainapp.Data.Info.RecordingType,"IntanDat") || strcmp(app.Mainapp.Data.Info.RecordingType,"IntanRHD")  
                if ~isempty(app.EventInfo.DIChannel)
                    if strcmp(app.Mainapp.Data.Info.RecordingType,"IntanDat")     
                        app.NrInputChinfolderEditField.Value = num2str(length(app.EventInfo.DIChannel));
                        iterationlength = length(app.EventInfo.DIChannel);
                    elseif strcmp(app.Mainapp.Data.Info.RecordingType,"IntanRHD")  
                        app.NrInputChinfolderEditField.Value = num2str(app.EventInfo.DIChannel);
                        iterationlength = app.EventInfo.DIChannel;
                    end
    
                    % Initialize an empty string
                    ChannelSelectionString = '';
    
                    % Loop through numbers from 1 to 10
                    for i = 1:iterationlength
                        % Append each number to the string
                        ChannelSelectionString = [ChannelSelectionString, num2str(i)];
                        
                        % If it's not the last number, add a comma
                        if i < iterationlength
                            ChannelSelectionString = [ChannelSelectionString, ', '];
                        end
                    end  
                    
                    app.InputChannelSelectionEditField.Value = ChannelSelectionString;
                else
                    app.NrInputChinfolderEditField.Value = num2str(0);
                end

            elseif strcmp(app.Mainapp.Data.Info.RecordingType,"Open Ephys")
                if ~isempty(app.EventInfo)
                    app.NrInputChinfolderEditField.Value = num2str(length(unique(app.EventInfo{1}.line)));
                    % Initialize an empty string
                    ChannelSelectionString = '';
    
                    % Loop through numbers from 1 to 10
                    for i = 1:length(unique(app.EventInfo{1}.line))
                        % Append each number to the string
                        ChannelSelectionString = [ChannelSelectionString, num2str(i)];
                        
                        % If it's not the last number, add a comma
                        if i < length(unique(app.EventInfo{1}.line))
                            ChannelSelectionString = [ChannelSelectionString, ', '];
                        end
                    end  
        
                    % This holds the index of nodes containing events
                    %% Search for open ephys data
                    [stringArray] = Utility_Extract_Contents_of_Folder(app.Path);
                    FolderIndicieWithEphysData = [];
                    for foldercontents = 1:length(stringArray)
                        if contains(stringArray(foldercontents),'Record Node')
                            if isfolder(strcat(app.Path,'\',stringArray(foldercontents)))
                                FolderIndicieWithEphysData = [FolderIndicieWithEphysData,foldercontents];
                            end
                        end
                    end
                    Nodefolder = stringArray(FolderIndicieWithEphysData);
                    
                    placeholder = {};
                    app.FileTypeDropDown.Items = placeholder;
                    for i = 1:length(FilesIndex)
                        app.FileTypeDropDown.Items{i} = convertStringsToChars(Nodefolder(FilesIndex(i)));
                    end
    
                    app.InputChannelSelectionEditField.Value = ChannelSelectionString;
                        
                    % Convert the table to text
                    tableText = evalc('disp(app.EventInfo{1})');  % Convert table to string
                    
                    % delte unwanted parts of the char
                    cutpartsindicies = find(tableText =='>');
                    tableText(1:cutpartsindicies(end)) = [];
                    tableText = ['line;  SampleNumber;  TimeStamp;  NodeID;  state',tableText];
                    % Display the text in the Text Area
                    app.TextArea_2.Value = tableText;
                end
            else % No events found
                app.NrInputChinfolderEditField.Value = "";
                app.InputChannelSelectionEditField.Value = "";
                Placeholder = {};
                app.FileTypeDropDown.Items = Placeholder;
                app.TextArea_2.Value = "Warning: No event data found in folder the dataset was extracted from. Please select a different folder.";
                return;
            end

            % If no ending with the format specified above was found
            if FileEndingsExist == 0 && strcmp(app.Mainapp.Data.Info.RecordingType,"Neuralynx") 
                app.TextArea_2.Value = "Warning: No event data found in folder the dataset was extracted from. Please select a different folder.";
                app.NrInputChinfolderEditField.Value = "";
                app.InputChannelSelectionEditField.Value = "";
                return;
            end

            if FileEndingsExist ~= 0 && strcmp(app.Mainapp.Data.Info.RecordingType,"Neuralynx") 
                % FilesIndex{1} = files with .nev ending holding event data
                app.NrInputChinfolderEditField.Value = num2str(sum(FilesIndex{1}));
                app.InputChannelSelectionEditField.Value = num2str(1:sum(FilesIndex{1}));

            end

            if isempty(DatFilePaths) && strcmp(app.Mainapp.Data.Info.RecordingType,"IntanDat")  
                app.TextArea_2.Value = "Warning: No event data found in folder the dataset was extracted from. Please select a different folder.";
                app.NrInputChinfolderEditField.Value = "";
                app.InputChannelSelectionEditField.Value = "";
                app.FileTypeDropDown.Value = "Digital Inputs";
                return;
            end

            %% Initiate Event Related Data Panel
            if ~isfield(app.Mainapp.Data,'Events') 
                app.Panel_2.Enable = "off";
                app.ExtractEventRelatedDataButton.Enable = "off";
                app.TextArea_3.Value = "No event channel data extracted. Please first extract events in the panel above.";
            else
                app.Panel_2.Enable = "on";
                app.ExtractEventRelatedDataButton.Enable = "on";

                for i = 1:length(app.Mainapp.Data.Events)
                    texttoshow{i} =  app.Mainapp.Data.Info.EventChannelNames{i};
                end
    
                app.EventChanneltoUseDropDown.Items = texttoshow;
    
                texttoshow = {};
                n = 1;
                if isfield(app.Mainapp.Data,'Raw')
                    texttoshow{n} = 'Raw';
                    n = n+1;
                end
    
                if isfield(app.Mainapp.Data,'Preprocessed')
                    texttoshow{n} = 'Preprocessed';
                end
    
                app.DatatouseDropDown.Items = texttoshow;
    
                if ~isfield(app.Mainapp.Data,'Events')
                    app.TextArea_3.Value = "No events extracted. Please extract events first in the above panel.";
                else

                    if isfield(app.Mainapp.Data,'EventRelatedData')
                        app.TextArea_3.Value = "Event Related Data found.";
        
                        app.TextArea_3.Value = [app.TextArea_3.Value;"Number of Events:";size(app.Mainapp.Data.EventRelatedData,2);"Channel:";app.Mainapp.Data.Info.EventRelatedDataChannel;"Time Range:";app.Mainapp.Data.Info.EventRelatedDataTimeRange;"Data Type:";app.Mainapp.Data.Info.EventRelatedDataType];
                    else
                        app.TextArea_3.Value = "No Event Related Data found. Use this panel to extract event related data.";
                    end
                end
            end
        end

        % Value changed function: FileTypeDropDown
        function FileTypeDropDownValueChanged(app, event)
            if strcmp(app.Mainapp.Data.Info.RecordingType,"IntanDat") 
                [app.EventInfo.DIChannel,app.EventInfo.ADCChannel,app.EventInfo.AUXChannel,~] = Extract_Events_Module_Organize_Window_Intan_Dat(app,[],"ChangedFileType",app.EventInfo.DIChannel,app.EventInfo.ADCChannel,app.EventInfo.AUXChannel,[],[],app.FileTypeDropDown.Value);
            elseif strcmp(app.Mainapp.Data.Info.RecordingType,"IntanRHD") 
                [app.EventInfo.DIChannel,app.EventInfo.ADCChannel,app.EventInfo.AUXChannel,~,~] = Extract_Events_Module_Organize_Window_Intan_RHD(app,[],"ChangedFileType",app.EventInfo.DIChannel,app.EventInfo.ADCChannel,app.EventInfo.AUXChannel,app.FileTypeDropDown.Value);
            end

            app.TextArea_2.Value = app.FilesinPath;
        end

        % Button pushed function: SetfoldermanuallyButton
        function SetfoldermanuallyButtonPushed(app, event)
            if ~isempty(app.EventInfo)
                app.EventInfo = [];
            end
            %% Get user selcted folder and save as app.selectedFolder
            % Prompt the user to select a folder
            NewPath = uigetdir('Navigate to - and select the Group folder containing all the animals');
            if NewPath == 0
                disp('No folder selected. Exiting.');
                return;
            end

            app.Path = NewPath;

            app.TextArea.Value = app.Path;
            pause(0.2);

            if strcmp(app.Mainapp.Data.Info.RecordingType,"IntanDat")            
                app.RecordingSystem.Value = "Intan .dat files";
            elseif strcmp(app.Mainapp.Data.Info.RecordingType,"IntanRHD")           
                %% Set up whats shown in the GUI
                app.RecordingSystem.Value = "Intan .rhd files";

            elseif strcmp(app.Mainapp.Data.Info.RecordingType,"Neuralynx") 
                % Setup GUI fields when data was recorded with Neuralynx
                app.FileTypeDropDown.Enable = "off";
                app.FileTypeDropDown.Items = {'Events.nev'};
                app.AnalogThresholdVEditField.Value = "";
                app.AnalogThresholdVEditField.Enable = "off";
                app.RecordingSystem.Value = "Neuralynx files";
            end

            %% Function to determine which event channel area vailable in folder based on names of folder components

            [app.EventInfo,FileEndingsExist,FilesIndex,DatFilePaths,app.FilesinPath] = Extract_Events_Module_Determine_Available_EventChannel(app.Mainapp.Data,app.Path,app.TextArea,app.TextArea_2,app.FileTypeDropDown.Value);

            if isempty(FileEndingsExist)
                FileEndingsExist = 1;
            end
            
            %% Set up whats shown in the GUI
            % Initialize the first things shown in GUI: Digital Channel

            % Analyse how many input event channel exist#
            if strcmp(app.Mainapp.Data.Info.RecordingType,"IntanDat")  || strcmp(app.Mainapp.Data.Info.RecordingType,"IntanRHD")  
                if ~isempty(app.EventInfo.DIChannel)
                    if strcmp(app.Mainapp.Data.Info.RecordingType,"IntanDat")     
                        app.NrInputChinfolderEditField.Value = num2str(length(app.EventInfo.DIChannel));
                        iterationlength = length(app.EventInfo.DIChannel);
                    elseif strcmp(app.Mainapp.Data.Info.RecordingType,"IntanRHD")  
                        app.NrInputChinfolderEditField.Value = num2str(app.EventInfo.DIChannel);
                        iterationlength = app.EventInfo.DIChannel;
                    end
    
                    % Initialize an empty string
                    ChannelSelectionString = '';
    
                    % Loop through numbers from 1 to 10
                    for i = 1:iterationlength
                        % Append each number to the string
                        ChannelSelectionString = [ChannelSelectionString, num2str(i)];
                        
                        % If it's not the last number, add a comma
                        if i < iterationlength
                            ChannelSelectionString = [ChannelSelectionString, ', '];
                        end
                    end  
                    
                    app.InputChannelSelectionEditField.Value = ChannelSelectionString;
                else
                    app.NrInputChinfolderEditField.Value = num2str(0);
                end

            elseif strcmp(app.Mainapp.Data.Info.RecordingType,"Open Ephys")
                if ~isempty(app.EventInfo)
                    app.NrInputChinfolderEditField.Value = num2str(length(unique(app.EventInfo{1}.line)));
                    % Initialize an empty string
                    ChannelSelectionString = '';
    
                    % Loop through numbers from 1 to 10
                    for i = 1:length(unique(app.EventInfo{1}.line))
                        % Append each number to the string
                        ChannelSelectionString = [ChannelSelectionString, num2str(i)];
                        
                        % If it's not the last number, add a comma
                        if i < length(unique(app.EventInfo{1}.line))
                            ChannelSelectionString = [ChannelSelectionString, ', '];
                        end
                    end  
        
                    % This holds the index of nodes containing events
                    %% Search for open ephys data
                    [stringArray] = Utility_Extract_Contents_of_Folder(app.Path);
                    FolderIndicieWithEphysData = [];
                    for foldercontents = 1:length(stringArray)
                        if contains(stringArray(foldercontents),'Record Node')
                            if isfolder(strcat(app.Path,'\',stringArray(foldercontents)))
                                FolderIndicieWithEphysData = [FolderIndicieWithEphysData,foldercontents];
                            end
                        end
                    end
                    Nodefolder = stringArray(FolderIndicieWithEphysData);
                    
                    placeholder = {};
                    app.FileTypeDropDown.Items = placeholder;
                    for i = 1:length(FilesIndex)
                        app.FileTypeDropDown.Items{i} = convertStringsToChars(Nodefolder(FilesIndex(i)));
                    end
    
                    app.InputChannelSelectionEditField.Value = ChannelSelectionString;
                        
                    % Convert the table to text
                    tableText = evalc('disp(app.EventInfo{1})');  % Convert table to string
                    
                    % delte unwanted parts of the char
                    cutpartsindicies = find(tableText =='>');
                    tableText(1:cutpartsindicies(end)) = [];
                    tableText = ['line;  SampleNumber;  TimeStamp;  NodeID;  state',tableText];
                    % Display the text in the Text Area
                    app.TextArea_2.Value = tableText;
                else
                    app.NrInputChinfolderEditField.Value = "";
                    app.InputChannelSelectionEditField.Value = "";
                    Placeholder = {};
                    app.FileTypeDropDown.Items = Placeholder;
                    app.TextArea_2.Value = "Warning: No event data found in folder the dataset was extracted from. Please select a different folder.";
                    return;
                end
            else % No events found
                app.NrInputChinfolderEditField.Value = "";
                app.InputChannelSelectionEditField.Value = "";
                Placeholder = {};
                app.FileTypeDropDown.Items = Placeholder;
                app.TextArea_2.Value = "Warning: No event data found in folder the dataset was extracted from. Please select a different folder.";
                return;
            end

            % If no ending with the format specified above was found
            if FileEndingsExist == 0 && strcmp(app.Mainapp.Data.Info.RecordingType,"Neuralynx") 
                app.TextArea_2.Value = "Warning: Selected folder doesnt contain data with events. Please select a different folder.";
                app.NrInputChinfolderEditField.Value = "";
                app.InputChannelSelectionEditField.Value = "";
                return;
            end

            if FileEndingsExist ~= 0 && strcmp(app.Mainapp.Data.Info.RecordingType,"Neuralynx") 
                % FilesIndex{1} = files with .nev ending holding event data
                app.NrInputChinfolderEditField.Value = num2str(sum(FilesIndex{1}));
                app.InputChannelSelectionEditField.Value = num2str(1:sum(FilesIndex{1}));

            end

            if strcmp(app.Mainapp.Data.Info.RecordingType,"IntanDat")  
                app.FileTypeDropDown.Value = "Digital Inputs";
            end

            if isempty(DatFilePaths) && strcmp(app.Mainapp.Data.Info.RecordingType,"IntanDat")  
                app.TextArea_2.Value = "Warning: Selected folder doesnt contain data with events. Please select a different folder.";
                app.NrInputChinfolderEditField.Value = "";
                app.InputChannelSelectionEditField.Value = "";
                app.FileTypeDropDown.Value = "Digital Inputs";
                return;
            end  
        end

        % Button pushed function: ShowInputChannelPlotButton
        function ShowInputChannelPlotButtonPushed(app, event)
            
            if isempty(app.EventInfo.DIChannel) && isempty(app.EventInfo.ADCChannel) && isempty(app.EventInfo.AUXChannel)
                f = msgbox("Error: No Event Channel found.");
                return;
            end

            %% For Single Intan RHD Files
            if strcmp(app.Mainapp.Data.Info.RecordingType,"IntanRHD") && app.ExtractedRHDEventsFlag == 0
            
                [RhdFilePaths] = LoadIntanRHDFiles(app.Path);
                LastDashIndex = find(RhdFilePaths == '\');
                RHDPath = RhdFilePaths(1:LastDashIndex(end));
                RHDFiles = RhdFilePaths(LastDashIndex(end)+1:end);

                app.TextArea_2.Value = "Extracting Event Input Channel from RHD file";

                [~,app.RHDAllChannelData.board_adc_data,~,app.RHDAllChannelData.board_dig_in_data,~,~,~,app.RHDAllChannelData.aux_input_data,~] = Intan_RHD2000_Data_Extraction (RHDFiles,RHDPath,"Extracting",app.TextArea_2);
            
                app.ExtractedRHDEventsFlag = 1;

                app.TextArea_2.Value = "Event Channel Extraction to show Event Channel Plots finished.";

            end

            Show_Event_Channel_Window(app.Mainapp,app);
        end

        % Button pushed function: StartEventExtractionButton
        function StartEventExtractionButtonPushed(app, event)
            if strcmp(app.Mainapp.Data.Info.RecordingType,"IntanRHD") || strcmp(app.Mainapp.Data.Info.RecordingType,"IntanDat")
                if isempty(app.EventInfo.DIChannel) && isempty(app.EventInfo.ADCChannel) && isempty(app.EventInfo.AUXChannel)
                    f = msgbox("Error: No Event Channel found.");
                    return;
                end
            end

            % Split the string based on the delimiter ','
            IndividualStrings = strsplit(app.InputChannelSelectionEditField.Value, ',');
            
            % Convert the cell array of strings to a numeric vector
            InputChannelSelection = str2double(IndividualStrings);

            [app.Mainapp.Data,EventChannelDropDown,app.RHDAllChannelData,app.ExtractedRHDEventsFlag] = Extract_Events_Module_Main_Function(app.Mainapp.Data,app.EventInfo,app.Path,app.Mainapp.Data.Info.RecordingType,app.FileTypeDropDown.Value,app.AnalogThresholdVEditField.Value,InputChannelSelection,app.ExtractedRHDEventsFlag,app.TextArea_2,app.RHDAllChannelData);

            if ~isempty(EventChannelDropDown)  % If events succesfully extracted
                app.Mainapp.EventChannelDropDown.Items = EventChannelDropDown;
                app.Mainapp.CurrentEventChannel = 1;
                app.Mainapp.PlotEvents = "Non";
                pause(0.2);
        
                app.Mainapp.EventDataCheckBox.Value = 0;
                app.Mainapp.EventDataCheckBox.Enable = "on";
                app.Mainapp.EventChannelDropDown.Enable = "on";

                %% Activate an Initiate Event related data extraction panel

                app.Panel_2.Enable = "on";

                for i = 1:length(app.Mainapp.Data.Events)
                    texttoshow{i} = app.Mainapp.Data.Info.EventChannelNames{i};
                end
    
                app.EventChanneltoUseDropDown.Items = texttoshow;
    
                texttoshow = {};
                n = 1;
                if isfield(app.Mainapp.Data,'Raw')
                    texttoshow{n} = 'Raw';
                    n = n+1;
                end
    
                if isfield(app.Mainapp.Data,'Preprocessed')
                    texttoshow{n} = 'Preprocessed';
                end
    
                app.DatatouseDropDown.Items = texttoshow;
    
                app.TextArea_3.Value = "No Event Related Data found. Use this panel to extract event related data.";
                
                if isfield(app.Mainapp.Data,'EventRelatedData')
                    if ~isempty(app.Mainapp.Data.EventRelatedData)
                        msgbox("Existing event related data found and overwritten");
                        fieldsToDelete = {'EventRelatedData'};
                        app.Mainapp.Data = rmfield(app.Mainapp.Data, fieldsToDelete);
                        fieldsToDelete = {'EventRelatedDataChannel','EventRelatedDataType','EventRelatedDataTimeRange'};
                        app.Mainapp.Data.Info = rmfield(app.Mainapp.Data.Info, fieldsToDelete);
                    end
                end
                
                if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                    if ~isempty(app.Mainapp.Data.PreprocessedEventRelatedData)
                        msgbox("Existing preprocessed event related data found and deleted");
                        fieldsToDelete = {'PreprocessedEventRelatedData'};
                        app.Mainapp.Data = rmfield(app.Mainapp.Data, fieldsToDelete);
                        fieldsToDelete = {'EventRelatedPreprocessing'};
                        app.Mainapp.Data.Info = rmfield(app.Mainapp.Data.Info, fieldsToDelete);
                    end
                end

                app.Panel_2.Enable = "on";
                app.ExtractEventRelatedDataButton.Enable = "on";
                
            else % If no events extracted

                if isfield(app.Mainapp.Data,'EventRelatedData')
                    if ~isempty(app.Mainapp.Data.EventRelatedData)
                        msgbox("Existing event related data found and overwritten");
                        fieldsToDelete = {'EventRelatedData'};
                        app.Mainapp.Data = rmfield(app.Mainapp.Data, fieldsToDelete);
                        fieldsToDelete = {'EventRelatedDataChannel','EventRelatedDataType','EventRelatedDataTimeRange'};
                        app.Mainapp.Data.Info = rmfield(app.Mainapp.Data.Info, fieldsToDelete);
                    end
                end
                
                if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                    if ~isempty(app.Mainapp.Data.PreprocessedEventRelatedData)
                        msgbox("Existing preprocessed event related data found and deleted");
                        fieldsToDelete = {'PreprocessedEventRelatedData'};
                        app.Mainapp.Data = rmfield(app.Mainapp.Data, fieldsToDelete);
                        fieldsToDelete = {'EventRelatedPreprocessing'};
                        app.Mainapp.Data.Info = rmfield(app.Mainapp.Data.Info, fieldsToDelete);
                    end
                end

                if isfield(app.Mainapp.Data,'Events')
                    fieldsToDelete = {'Events'};
                    % Delete fields
                    app.Mainapp.Data = rmfield(app.Mainapp.Data, fieldsToDelete);
                    fieldsToDelete = {'EventChannelType', 'EventChannelNames'};
                    % Delete fields
                    app.Mainapp.Data.Info = rmfield(app.Mainapp.Data.Info, fieldsToDelete);
                end

                if isfield(app.Mainapp.Data,'EventRelatedSpikes')
                    fieldsToDelete = {'EventRelatedSpikes'};
                    % Delete fields
                    app.Mainapp.Data = rmfield(app.Mainapp.Data, fieldsToDelete);
                end
                        
                if isfield(app.Mainapp.Data,'EventRelatedData')
                    fieldsToDelete = {'EventRelatedData'};
                    % Delete fields
                    app.Mainapp.Data = rmfield(app.Mainapp.Data, fieldsToDelete);
                   
                    fieldsToDelete = {'EventRelatedDataChannel','EventRelatedDataType','EventRelatedDataTimeRange'};
                    app.Mainapp.Data.Info = rmfield(app.Mainapp.Data.Info, fieldsToDelete);
                end

                PlaceHolder = {};
                app.Mainapp.EventChannelDropDown.Items = PlaceHolder;
                app.Mainapp.EventChannelDropDown.Enable = "off";
                app.Mainapp.EventDataCheckBox.Enable = "off";
                app.Mainapp.EventDataCheckBox.Value = 0;
                app.Mainapp.PlotEvents = "Non";

                app.Panel_2.Enable = "off";
                app.ExtractEventRelatedDataButton.Enable = "off";
                app.TextArea_3.Value = "No events extracted. Please extract events first in the panel above.";
                
            end

            %% Show new Info Header entries in main window

            Utility_Show_Info_Loaded_Data(app.Mainapp);

            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            app.Mainapp.UIAxes.NextPlot = "replace"; 
            plot(app.Mainapp.UIAxes,0,0);
            app.Mainapp.UIAxes_2.NextPlot = "replace"; 
            plot(app.Mainapp.UIAxes_2,0,0);

            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: EventChanneltoUseDropDown
        function EventChanneltoUseDropDownValueChanged(app, event)
            value = app.EventChanneltoUseDropDown.Value;
            EventChannelNr = [];
            for i = 1:length(app.Mainapp.Data.Info.EventChannelNames)
                if strcmp(app.EventChanneltoUseDropDown.Value,app.Mainapp.Data.Info.EventChannelNames{i})
                    EventChannelNr = i;
                end
            end

            app.TextArea_3.Value = ["Number of events for Channel ",app.EventChanneltoUseDropDown.Value," ",num2str(length(app.Mainapp.Data.Events{EventChannelNr}))];

        end

        % Value changed function: TimeWindowbeforeeventssEditField
        function TimeWindowbeforeeventssEditFieldValueChanged(app, event)
            [app.TimeWindowbeforeeventssEditField.Value] = Utility_SimpleCheckInputs(app.TimeWindowbeforeeventssEditField.Value,"One",'0.2');
            value = app.TimeWindowbeforeeventssEditField.Value;
            app.TimearoundEvent(1) = str2double(value);
        end

        % Value changed function: TimeWindowaftereventssEditField
        function TimeWindowaftereventssEditFieldValueChanged(app, event)
            [app.TimeWindowaftereventssEditField.Value] = Utility_SimpleCheckInputs(app.TimeWindowaftereventssEditField.Value,"One",'0.5');
            value = app.TimeWindowaftereventssEditField.Value;
            app.TimearoundEvent(2) = str2double(value);
        end

        % Button pushed function: ExtractEventRelatedDataButton
        function ExtractEventRelatedDataButtonPushed(app, event)
            [app.Mainapp.Data,app.TimearoundEvent] = Event_Module_Extract_Event_Related_Data(app.Mainapp.Data,app.EventChanneltoUseDropDown.Value,app.TimeWindowbeforeeventssEditField.Value,app.TimeWindowaftereventssEditField.Value,app.DatatouseDropDown.Value);

            if isfield(app.Mainapp.Data,'EventRelatedData') 
                if ~isempty(app.Mainapp.Data.EventRelatedData)
                     app.TextArea_3.Value = strcat("Success. Event Related Data can now be Analysed. Found ",num2str(size(app.Mainapp.Data.EventRelatedData,1))," Channel, ",num2str(size(app.Mainapp.Data.EventRelatedData,2))," Events and ",num2str(size(app.Mainapp.Data.EventRelatedData,3))," Time Points.");
                     Utility_Show_Info_Loaded_Data(app.Mainapp);
                else
                    app.TextArea_3.Value = "No Event Related Data could be Extracted.";
                end
            end
        end

        % Value changed function: AnalogThresholdVEditField
        function AnalogThresholdVEditFieldValueChanged(app, event)
            [app.AnalogThresholdVEditField.Value] = Utility_SimpleCheckInputs(app.AnalogThresholdVEditField.Value,"One",'1');
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ExtractEventsWindowUIFigure = uifigure('Visible', 'off');
            app.ExtractEventsWindowUIFigure.Name = 'Extract Events Window';
            app.ExtractEventsWindowUIFigure.Icon = 'Logo.png';
            app.ExtractEventsfromRawEventChannelPanel = uipanel(app.ExtractEventsWindowUIFigure);
            app.ExtractEventsfromRawEventChannelPanel.Title = 'Extract Events from Raw Event Channel';
            app.Panel = uipanel(app.ExtractEventsfromRawEventChannelPanel);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.FileTypeDropDown = uidropdown(app.GridLayout2);
            app.FileTypeDropDown.Items = {'Digital Inputs', 'Analog Input', 'AUX Inputs'};
            app.FileTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @FileTypeDropDownValueChanged, true);
            app.FileTypeDropDown.Value = 'Digital Inputs';
            app.SelectFileTypeLabel = uilabel(app.GridLayout2);
            app.SelectFileTypeLabel.Text = 'Select File Type';
            app.RecordingSystemLabel = uilabel(app.GridLayout2);
            app.RecordingSystemLabel.Text = 'Recording System';
            app.InputChannelSelectionEditFieldLabel = uilabel(app.GridLayout2);
            app.InputChannelSelectionEditFieldLabel.Text = {'Input Channel'; 'Selection'};
            app.InputChannelSelectionEditField = uieditfield(app.GridLayout2, 'text');
            app.NrInputChinfolderEditFieldLabel = uilabel(app.GridLayout2);
            app.NrInputChinfolderEditFieldLabel.Text = {'Nr. Input Ch.'; 'in folder'};
            app.NrInputChinfolderEditField = uieditfield(app.GridLayout2, 'text');
            app.NrInputChinfolderEditField.Editable = 'off';
            app.TriggerRangeVLabel = uilabel(app.GridLayout2);
            app.TriggerRangeVLabel.Text = 'Trigger Treshold [V]';
            app.AnalogThresholdVEditField = uieditfield(app.GridLayout2, 'text');
            app.AnalogThresholdVEditField.ValueChangedFcn = createCallbackFcn(app, @AnalogThresholdVEditFieldValueChanged, true);
            app.AnalogThresholdVEditField.Value = '1';
            app.RecordingSystem = uieditfield(app.GridLayout2, 'text');
            app.RecordingSystem.Editable = 'off';
            app.TextArea = uitextarea(app.ExtractEventsfromRawEventChannelPanel);
            app.FilePathLabel = uilabel(app.ExtractEventsfromRawEventChannelPanel);
            app.FilePathLabel.Text = 'File Path';
            app.TextArea_2 = uitextarea(app.ExtractEventsfromRawEventChannelPanel);
            app.FilesinPathLabel = uilabel(app.ExtractEventsfromRawEventChannelPanel);
            app.FilesinPathLabel.Text = 'Files in Path';
            app.StartEventExtractionButton = uibutton(app.ExtractEventsfromRawEventChannelPanel, 'push');
            app.StartEventExtractionButton.ButtonPushedFcn = createCallbackFcn(app, @StartEventExtractionButtonPushed, true);
            app.StartEventExtractionButton.Text = 'Start Event Extraction';
            app.SetfoldermanuallyButton = uibutton(app.ExtractEventsfromRawEventChannelPanel, 'push');
            app.SetfoldermanuallyButton.ButtonPushedFcn = createCallbackFcn(app, @SetfoldermanuallyButtonPushed, true);
            app.SetfoldermanuallyButton.Text = {'Set folder '; 'manually'};
            app.ShowInputChannelPlotButton = uibutton(app.ExtractEventsfromRawEventChannelPanel, 'push');
            app.ShowInputChannelPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ShowInputChannelPlotButtonPushed, true);
            app.ShowInputChannelPlotButton.Text = {'Show Input Channel'; 'Plot'};
            app.ExtractEventsWindowLabel = uilabel(app.ExtractEventsWindowUIFigure);
            app.ExtractEventsWindowLabel.Text = 'Extract Events Window';
            app.Image = uiimage(app.ExtractEventsWindowUIFigure);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodToolboxLabel = uilabel(app.ExtractEventsWindowUIFigure);
            app.NeuromodToolboxLabel.Text = 'Neuromod Toolbox';
            app.CloseButton = uibutton(app.ExtractEventsWindowUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.ExtractEventRelatedDataPanel = uipanel(app.ExtractEventsWindowUIFigure);
            app.ExtractEventRelatedDataPanel.Title = 'Extract Event Related Data';
            app.Panel_2 = uipanel(app.ExtractEventRelatedDataPanel);
            app.TimeWindowbeforeeventssEditFieldLabel = uilabel(app.Panel_2);
            app.TimeWindowbeforeeventssEditFieldLabel.Text = {'Time Window '; 'before events [s]'};
            app.TimeWindowbeforeeventssEditField = uieditfield(app.Panel_2, 'text');
            app.TimeWindowbeforeeventssEditField.ValueChangedFcn = createCallbackFcn(app, @TimeWindowbeforeeventssEditFieldValueChanged, true);
            app.TimeWindowbeforeeventssEditField.Value = '0.2';
            app.TimeWindowaftereventssEditFieldLabel = uilabel(app.Panel_2);
            app.TimeWindowaftereventssEditFieldLabel.Text = {'Time Window '; 'after events [s]'};
            app.TimeWindowaftereventssEditField = uieditfield(app.Panel_2, 'text');
            app.TimeWindowaftereventssEditField.ValueChangedFcn = createCallbackFcn(app, @TimeWindowaftereventssEditFieldValueChanged, true);
            app.TimeWindowaftereventssEditField.Value = '0.5';
            app.DatatouseDropDownLabel = uilabel(app.Panel_2);
            app.DatatouseDropDownLabel.Text = 'Data to use';
            app.DatatouseDropDown = uidropdown(app.Panel_2);
            app.DatatouseDropDown.Items = {''};
            app.DatatouseDropDown.Value = '';
            app.EventChanneltoUseDropDownLabel = uilabel(app.Panel_2);
            app.EventChanneltoUseDropDownLabel.Text = {'Event Channel'; 'to Use'};
            app.EventChanneltoUseDropDown = uidropdown(app.Panel_2);
            app.EventChanneltoUseDropDown.Items = {''};
            app.EventChanneltoUseDropDown.ValueChangedFcn = createCallbackFcn(app, @EventChanneltoUseDropDownValueChanged, true);
            app.EventChanneltoUseDropDown.Value = '';
            app.TextArea_3 = uitextarea(app.Panel_2);
            app.ExtractEventRelatedDataButton = uibutton(app.ExtractEventRelatedDataPanel, 'push');
            app.ExtractEventRelatedDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractEventRelatedDataButtonPushed, true);
            app.ExtractEventRelatedDataButton.Text = {'Extract Event '; 'Related Data'};
            % Show the figure after all components are created
            app.ExtractEventsWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Extract_Events_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ExtractEventsWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ExtractEventsWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ExtractEventsWindowUIFigure)
        end
    end
end