classdef Show_Event_Channel_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Show_Input_Channel_WindowUIFigure  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        RightPanel                      matlab.ui.container.Panel
        CloseButton                     matlab.ui.control.Button
        ShowAllInputChannelLabel        matlab.ui.control.Label
        NeuromodLabel                   matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        PlotOptionsPanel                matlab.ui.container.Panel
        StatestoPlotonlyOpenEphysLabel  matlab.ui.control.Label
        FileTypeDropDown_3              matlab.ui.control.DropDown
        DowsampledSampleRateHzEditField  matlab.ui.control.EditField
        DowsampledSampleRateHzEditFieldLabel  matlab.ui.control.Label
        SelectInputChannelNumberLabel   matlab.ui.control.Label
        FileTypeDropDown_2              matlab.ui.control.DropDown
        SelectInputChannelTypeLabel     matlab.ui.control.Label
        FileTypeDropDown                matlab.ui.control.DropDown
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Mainapp = []; % Description
        EWapp = [];
        ChannelWithStuff = [];
        EventChannelNames = [];
        AdditionalEventInfo = [];
        startTimestamp = []; % OE
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp, EWapp)
            % Moce this window in the middle of thescreen
            movegui(app.Show_Input_Channel_WindowUIFigure, 'center');

            app.Mainapp = Mainapp;
            app.EWapp = EWapp;
             
            if ~strcmp(app.EWapp.Mainapp.Data.Info.RecordingType,"Open Ephys")
                app.FileTypeDropDown_3.Enable = "off";
            end

            app.DowsampledSampleRateHzEditField.Value = "1000";

            ChannelPlot = 0;

            %% Determine for which kind of event channels data is present. First one that has data is plotted
            if strcmp(app.EWapp.Mainapp.Data.Info.RecordingType,"IntanDat") || strcmp(app.EWapp.Mainapp.Data.Info.RecordingType,"IntanRHD")
                if isempty(app.EWapp.EventInfo)
                    ChannelPlot = 0;
                else
                    fields = fieldnames(app.EWapp.EventInfo);
                    Extract_Events_Module_Show_ChannelPlots(app.EWapp.Mainapp.Data,fields{1},app.EWapp.Path,app,app.EWapp.RHDAllChannelData,"Initial",app.DowsampledSampleRateHzEditField.Value,app.Mainapp.executableFolder,app.FileTypeDropDown_3.Value);
                    ChannelPlot = 1;
                    app.ChannelWithStuff = fields{1};
                end
            end

            if strcmp(app.EWapp.Mainapp.Data.Info.RecordingType,"Spike2")
                Placeholder = {};
                app.FileTypeDropDown.Items = Placeholder;
                app.FileTypeDropDown.Items{1} = 'Spike 2 Event Channel';
                app.FileTypeDropDown.Value = 'Spike 2 Event Channel';
                ChannelRange = str2double(strsplit(app.EWapp.EventInfo.EventChannel,','));
                app.FileTypeDropDown_2.Items = Placeholder;
                for i = 1:length(ChannelRange)
                    app.FileTypeDropDown_2.Items{i} = num2str(ChannelRange(i));
                end

                if isempty(app.EWapp.EventInfo)
                    ChannelPlot = 0;
                else
                    fields = app.EWapp.EventInfo.EventChannel;
                    Extract_Events_Module_Show_ChannelPlots(app.EWapp.Mainapp.Data,fields,app.EWapp.Path,app,app.EWapp.RHDAllChannelData,"Initial",app.DowsampledSampleRateHzEditField.Value,app.Mainapp.executableFolder,app.FileTypeDropDown_3.Value);
                    ChannelPlot = 1;
                end
            end

            if strcmp(app.EWapp.Mainapp.Data.Info.RecordingType,"Open Ephys")
                
                app.SelectInputChannelTypeLabel.Text = "Recording Node";
                app.SelectInputChannelNumberLabel.Text = "Input Line Number";
                %% Populate selection dropdowns
                % Input Channel Type
                addpath(genpath("."));
                [stringArray] = Utility_Extract_Contents_of_Folder(app.EWapp.Path);
                FolderIndicieWithEphysData = [];
                for foldercontents = 1:length(stringArray)
                    if contains(stringArray(foldercontents),'Record Node')
                        if isfolder(strcat(app.EWapp.Path,'\',stringArray(foldercontents)))
                            FolderIndicieWithEphysData = [FolderIndicieWithEphysData,foldercontents];
                        end
                    end
                end

                AvailableNode = stringArray(FolderIndicieWithEphysData);
                app.FileTypeDropDown.Items = cellstr(AvailableNode);
                % Input Channel Number
                differentEventnames = unique(app.EWapp.EventInfo{1}.line);
                
                for i = 1:length(differentEventnames)
                    ChannNames{i} = num2str(differentEventnames(i)); 
                end

                app.FileTypeDropDown_2.Items = ChannNames;

                %% Extract and Show Channel
                [app.EWapp.EventInfo,FileEndingsExist,FilePaths,texttoshow,Info] = Extract_Events_Module_Determine_Available_EventChannel(app.EWapp.Mainapp.Data,app.EWapp.Path,app.FileTypeDropDown.Value);
                app.AdditionalEventInfo = Info;

                Extract_Events_Module_Set_Up_Window(app.EWapp,app.EWapp.Mainapp.Data,app.EWapp.EventInfo,app.EWapp.Path,FilePaths,FileEndingsExist,texttoshow,"Initial",Info);
                
                for i = 1:length(Info.AvailabelNodes)
                    if ~isempty(Info.AvailabelNodes{i})
                        NodeWithData = app.FileTypeDropDown.Items{Info.AvailabelNodes{i}};
                        break;
                    end
                end
                app.FileTypeDropDown.Value = NodeWithData;

                if isempty(app.EWapp.EventInfo)
                    ChannelPlot = 0;
                else
                    SelectedNode = [];
                    for i = 1:length(app.FileTypeDropDown.Items)
                        if strcmp(app.FileTypeDropDown.Items{i},app.FileTypeDropDown.Value)
                            SelectedNode = i;
                            break;
                        end
                    end
                    
                    if isempty(SelectedNode)
                        msgbox("Selected Node could not be identified.");
                    end

                    Nodenr = find(AvailableNode==app.FileTypeDropDown.Value);
                    OpenEphysEventInfo.RecordNode = Nodenr;
                    OpenEphysEventInfo.LineNumbers = differentEventnames;
                    OpenEphysEventInfo.SelectedLineNumbers = app.FileTypeDropDown_2.Value;
                    OpenEphysEventInfo.SelectedLineNode = SelectedNode;
                    OpenEphysEventInfo.Info = Info;

                    Extract_Events_Module_Show_ChannelPlots(app.EWapp.Mainapp.Data,OpenEphysEventInfo,app.EWapp.Path,app,app.EWapp.RHDAllChannelData,"Initial",app.DowsampledSampleRateHzEditField.Value,app.Mainapp.executableFolder,app.FileTypeDropDown_3.Value);
                    
                    ChannelPlot = 1;
                end
            end

            if strcmp(app.EWapp.Mainapp.Data.Info.RecordingType,"Neuralynx")

                app.SelectInputChannelNumberLabel.Text = "Input Line Number";
                app.FileTypeDropDown_2.Enable="off";
                FilesIndex = {};
                [stringarray] = Utility_Extract_Contents_of_Folder(app.EWapp.Path);
                FilesIndex = endsWith(stringarray, ".nev");

                if sum(FilesIndex)==0
                    ChannelPlot = 0; 
                    msgbox("No Input Channel found in selected folder");
                    delete(app);
                end

                Filename = strcat(app.EWapp.Path,'\',stringarray{FilesIndex==1});
                
                [event] = Extract_Events_Module_Extract_Events_Neuralynx(Filename,app.EWapp.Path);
                
                NumChannel = length(unique({event.type}));
                eventypes = unique({event.type});

                Placeholder = {};
                app.FileTypeDropDown.Items = Placeholder;
                for i = 1:length(eventypes)
                    app.FileTypeDropDown.Items{i} = eventypes{i};
                end

                % Just Select samples of the eventtypoe selected
                eventcell = {event.type};
                selectedfielytypeindicies = zeros(1,length({event.type}))+1;
                for i = 1:length({event.type})
                    if strcmp(eventcell{i},app.FileTypeDropDown.Value)
                        selectedfielytypeindicies(i) = 1;
                    else
                        selectedfielytypeindicies(i) = 0;
                    end
                end
            
                Eventsamples = double(cell2mat({event.sample}));
                Eventsamples(selectedfielytypeindicies==0) = [];

                if ~isempty(Eventsamples) 
                    for i = 1:length(Eventsamples)
                        app.EWapp.EventInfo.Samples{1}(i) = double(Eventsamples(i));
                    end
                    % Event 1 always sample 1
                    if app.EWapp.EventInfo.Samples{1}(1) == 1
                        app.EWapp.EventInfo.Samples{1}(1) = [];
                        msgbox("Warning: First sample is always 1 ans gets deleted.");
                    end

                    Zerosamples = app.EWapp.EventInfo.Samples{1} == 0;
                
                    if sum(Zerosamples) > 0
                        app.EWapp.EventInfo.Samples{1}(Zerosamples==1) = [];
                        msgbox("Warning: Indice of zero detected and deleted.")
                    end
                else
                     app.EWapp.EventInfo.Samples = [];
                     msgbox("Warning: No event samples found");
                     return;
                end

                Extract_Events_Module_Show_ChannelPlots(app.EWapp.Mainapp.Data,app.EWapp.EventInfo,app.EWapp.Path,app,app.EWapp.RHDAllChannelData,"Initial",app.DowsampledSampleRateHzEditField.Value,app.Mainapp.executableFolder,app.FileTypeDropDown_3.Value);
                ChannelPlot = 1; 
            end


            if ChannelPlot == 0 
                msgbox("No Input Channel found in selected folder");
                delete(app);
            end
        end

        % Value changed function: FileTypeDropDown
        function FileTypeDropDownValueChanged(app, event)
            %% Determine for which kind of event channels data is present. First one that has data is plotted
            if strcmp(app.EWapp.Mainapp.Data.Info.RecordingType,"IntanDat") || strcmp(app.EWapp.Mainapp.Data.Info.RecordingType,"IntanRHD")
                if ~isempty(app.EWapp.EventInfo)
                    fields = fieldnames(app.EWapp.EventInfo);
                    foundchannel = 0;
                    if strcmp(app.FileTypeDropDown.Value,"Digital Inputs")
                        for i = 1:length(fields)
                            if strcmp(fields{i},"DIChannel")
                                Fieldnametopass = fields{i};
                                foundchannel = 1;
                                break;
                            end
                        end
                    elseif strcmp(app.FileTypeDropDown.Value,"Analog Inputs")
                        for i = 1:length(fields)
                            if strcmp(fields{i},"ADCChannel")
                                Fieldnametopass = fields{i};
                                foundchannel = 1;
                                break;
                            end
                        end
                    elseif strcmp(app.FileTypeDropDown.Value,"AUX Inputs")
                        for i = 1:length(fields)
                            if strcmp(fields{i},"AUXChannel")
                                Fieldnametopass = fields{i};
                                foundchannel = 1;
                                break;
                            end
                        end
                    elseif strcmp(app.FileTypeDropDown.Value,"DIN Inputs")
                        for i = 1:length(fields)
                            if strcmp(fields{i},"DINChannel")
                                Fieldnametopass = fields{i};
                                foundchannel = 1;
                                break;
                            end
                        end
                    end

                    if foundchannel == 0
                        msgbox(strcat("No event data for ",app.FileTypeDropDown.Value," found"));
                        app.FileTypeDropDown.Value = event.PreviousValue;
                        return;
                    end
                    
                    Extract_Events_Module_Show_ChannelPlots(app.EWapp.Mainapp.Data,Fieldnametopass,app.EWapp.Path,app,app.EWapp.RHDAllChannelData,"Initial",app.DowsampledSampleRateHzEditField.Value,app.Mainapp.executableFolder,app.FileTypeDropDown_3.Value);
                                      
                else
                    msgbox("No Event Channel for Selection available!");
                end

            elseif strcmp(app.EWapp.Mainapp.Data.Info.RecordingType,"Open Ephys")
                
                %% Extract and Show Channel
                [app.EWapp.EventInfo,FileEndingsExist,FilePaths,texttoshow,Info] = Extract_Events_Module_Determine_Available_EventChannel(app.EWapp.Mainapp.Data,app.EWapp.Path,app.FileTypeDropDown.Value);
            
                Extract_Events_Module_Set_Up_Window(app.EWapp,app.EWapp.Mainapp.Data,app.EWapp.EventInfo,app.EWapp.Path,FilePaths,FileEndingsExist,texttoshow,"Initial",Info);
                                
                [stringArray] = Utility_Extract_Contents_of_Folder(app.EWapp.Path);
                FolderIndicieWithEphysData = [];
                for foldercontents = 1:length(stringArray)
                    if contains(stringArray(foldercontents),'Record Node')
                        if isfolder(strcat(app.EWapp.Path,'\',stringArray(foldercontents)))
                            FolderIndicieWithEphysData = [FolderIndicieWithEphysData,foldercontents];
                        end
                    end
                end

                AvailableNode = stringArray(FolderIndicieWithEphysData);

                if isempty(app.EWapp.EventInfo)
                    msgbox("Selected Node contains no events.");
                else
                    SelectedNode = [];
                    for i = 1:length(app.FileTypeDropDown.Items)
                        if strcmp(app.FileTypeDropDown.Items{i},app.FileTypeDropDown.Value)
                            SelectedNode = i;
                            break;
                        end
                    end

                    if isempty(SelectedNode)
                        msgbox("Selected Node contains no events.");
                    end
                    
                    % Input Channel Number
                    differentEventnames = unique(app.EWapp.EventInfo{SelectedNode}.line);
                
                    Nodenr = find(AvailableNode==app.FileTypeDropDown.Value);
                    OpenEphysEventInfo.RecordNode = Nodenr;
                    OpenEphysEventInfo.LineNumbers = differentEventnames;
                    
                    Placeholder = {};
                    app.FileTypeDropDown_2.Items = Placeholder;
                    for i = 1:length(differentEventnames)
                        app.FileTypeDropDown_2.Items{i} = num2str(differentEventnames(i));
                    end

                    OpenEphysEventInfo.SelectedLineNumbers = app.FileTypeDropDown_2.Value;
                    OpenEphysEventInfo.SelectedLineNode = SelectedNode;
                    OpenEphysEventInfo.Info = Info;

                    Extract_Events_Module_Show_ChannelPlots(app.EWapp.Mainapp.Data,OpenEphysEventInfo,app.EWapp.Path,app,app.EWapp.RHDAllChannelData,"Initial",app.DowsampledSampleRateHzEditField.Value,app.Mainapp.executableFolder,app.FileTypeDropDown_3.Value);
                end

            elseif strcmp(app.EWapp.Mainapp.Data.Info.RecordingType,"Neuralynx")

                FilesIndex = {};
                [stringarray] = Utility_Extract_Contents_of_Folder(app.EWapp.Path);
                FilesIndex = endsWith(stringarray, ".nev");

                if sum(FilesIndex)==0
                    ChannelPlot = 0; 
                    msgbox("No Input Channel found in selected folder");
                    return;
                end

                Filename = strcat(app.EWapp.Path,'\',stringarray{FilesIndex==1});
                
                [event] = Extract_Events_Module_Extract_Events_Neuralynx(Filename,app.EWapp.Path);
                
                % Just Select samples of the eventtypoe selected
                eventcell = {event.type};
                selectedfielytypeindicies = zeros(1,length({event.type}))+1;
                for i = 1:length({event.type})
                    if strcmp(eventcell{i},app.FileTypeDropDown.Value)
                        selectedfielytypeindicies(i) = 1;
                    else
                        selectedfielytypeindicies(i) = 0;
                    end
                end
            
                Eventsamples = double(cell2mat({event.sample}));
                Eventsamples(selectedfielytypeindicies==0) = [];

                if ~isempty(Eventsamples) 
                    for i = 1:length(Eventsamples)
                        app.EWapp.EventInfo.Samples{1}(i) = double(Eventsamples(i));
                    end
                    % Event 1 always sample 1
                    if app.EWapp.EventInfo.Samples{1}(1) == 1
                        app.EWapp.EventInfo.Samples{1}(1) = [];
                        msgbox("Warning: First sample is always 1 ans gets deleted.");
                    end

                    Zerosamples = app.EWapp.EventInfo.Samples{1} == 0;
                
                    if sum(Zerosamples) > 0
                        app.EWapp.EventInfo.Samples{1}(Zerosamples==1) = [];
                        msgbox("Warning: Indice of zero detected and deleted.")
                    end
                else
                     app.EWapp.EventInfo.Samples = [];
                     msgbox("Warning: No event samples found");
                     return;
                end

                Extract_Events_Module_Show_ChannelPlots(app.EWapp.Mainapp.Data,app.EWapp.EventInfo,app.EWapp.Path,app,app.EWapp.RHDAllChannelData,"Initial",app.DowsampledSampleRateHzEditField.Value,app.Mainapp.executableFolder,app.FileTypeDropDown_3.Value);
                     
            end
        end

        % Value changed function: FileTypeDropDown_2
        function FileTypeDropDown_2ValueChanged(app, event)
            %% Determine for which kind of event channels data is present. First one that has data is plotted
            if strcmp(app.EWapp.Mainapp.Data.Info.RecordingType,"IntanDat") || strcmp(app.EWapp.Mainapp.Data.Info.RecordingType,"IntanRHD")
                if ~isempty(app.EWapp.EventInfo)
                    fields = fieldnames(app.EWapp.EventInfo);

                    foundchannel = 0;
                    if strcmp(app.FileTypeDropDown.Value,"Digital Inputs")
                        for i = 1:length(fields)
                            if strcmp(fields{i},"DIChannel")
                                Fieldnametopass = fields{i};
                                foundchannel = 1;
                                break;
                            end
                        end
                    elseif strcmp(app.FileTypeDropDown.Value,"Analog Inputs")
                        for i = 1:length(fields)
                            if strcmp(fields{i},"ADCChannel")
                                Fieldnametopass = fields{i};
                                foundchannel = 1;
                                break;
                            end
                        end
                    elseif strcmp(app.FileTypeDropDown.Value,"AUX Inputs")
                        for i = 1:length(fields)
                            if strcmp(fields{i},"AUXChannel")
                                Fieldnametopass = fields{i};
                                foundchannel = 1;
                                break;
                            end
                        end
                    elseif strcmp(app.FileTypeDropDown.Value,"DIN Inputs")
                        for i = 1:length(fields)
                            if strcmp(fields{i},"DINChannel")
                                Fieldnametopass = fields{i};
                                foundchannel = 1;
                                break;
                            end
                        end
                    end

                    if foundchannel == 0
                        msgbox(strcat("No event data for ",app.FileTypeDropDown_2.Value," found"));
                        app.FileTypeDropDown_2.Value = event.PreviousValue;
                        return;
                    end
                    
                    Extract_Events_Module_Show_ChannelPlots(app.EWapp.Mainapp.Data,Fieldnametopass,app.EWapp.Path,app,app.EWapp.RHDAllChannelData,"Initial",app.DowsampledSampleRateHzEditField.Value,app.Mainapp.executableFolder,app.FileTypeDropDown_3.Value);
                                      
                    return;
                else
                    msgbox("No Input Channel found in selected folder!");
                end
            elseif strcmp(app.EWapp.Mainapp.Data.Info.RecordingType,"Open Ephys")
                
                %% Extract and Show Channel
                [app.EWapp.EventInfo,FileEndingsExist,FilePaths,texttoshow,Info] = Extract_Events_Module_Determine_Available_EventChannel(app.EWapp.Mainapp.Data,app.EWapp.Path,app.FileTypeDropDown.Value);
            
                Extract_Events_Module_Set_Up_Window(app.EWapp,app.EWapp.Mainapp.Data,app.EWapp.EventInfo,app.EWapp.Path,FilePaths,FileEndingsExist,texttoshow,"Initial",Info);
                                
                [stringArray] = Utility_Extract_Contents_of_Folder(app.EWapp.Path);
                FolderIndicieWithEphysData = [];
                for foldercontents = 1:length(stringArray)
                    if contains(stringArray(foldercontents),'Record Node')
                        if isfolder(strcat(app.EWapp.Path,'\',stringArray(foldercontents)))
                            FolderIndicieWithEphysData = [FolderIndicieWithEphysData,foldercontents];
                        end
                    end
                end

                AvailableNode = stringArray(FolderIndicieWithEphysData);

                if isempty(app.EWapp.EventInfo)
                    msgbox("Selected Node contains no events.");
                else
                    SelectedNode = [];
                    for i = 1:length(app.FileTypeDropDown.Items)
                        if strcmp(app.FileTypeDropDown.Items{i},app.FileTypeDropDown.Value)
                            SelectedNode = i;
                            break;
                        end
                    end

                    if isempty(SelectedNode)
                        msgbox("Selected Node contains no events.");
                    end
                    
                    % Input Channel Number
                    differentEventnames = unique(app.EWapp.EventInfo{1}.line);
                
                    Nodenr = find(AvailableNode==app.FileTypeDropDown.Value);
                    OpenEphysEventInfo.RecordNode = Nodenr;
                    OpenEphysEventInfo.LineNumbers = differentEventnames;
                    OpenEphysEventInfo.SelectedLineNumbers = app.FileTypeDropDown_2.Value;
                    OpenEphysEventInfo.SelectedLineNode = SelectedNode;
                    OpenEphysEventInfo.Info = Info;

                    Extract_Events_Module_Show_ChannelPlots(app.EWapp.Mainapp.Data,OpenEphysEventInfo,app.EWapp.Path,app,app.EWapp.RHDAllChannelData,"Initial",app.DowsampledSampleRateHzEditField.Value,app.Mainapp.executableFolder,app.FileTypeDropDown_3.Value);
                end

            elseif strcmp(app.EWapp.Mainapp.Data.Info.RecordingType,"Spike2")
                fields = app.EWapp.EventInfo.EventChannel;
                Extract_Events_Module_Show_ChannelPlots(app.EWapp.Mainapp.Data,fields,app.EWapp.Path,app,app.EWapp.RHDAllChannelData,"Initial",app.DowsampledSampleRateHzEditField.Value,app.Mainapp.executableFolder,app.FileTypeDropDown_3.Value);
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.EWapp.ShowChannelPlotWindow = [];
            delete(app);
        end

        % Value changed function: DowsampledSampleRateHzEditField
        function DowsampledSampleRateHzEditFieldValueChanged(app, event)
            [app.DowsampledSampleRateHzEditField.Value] = Utility_SimpleCheckInputs(app.DowsampledSampleRateHzEditField.Value,"One",'1000',0,0);

            downsamplerate = str2double(app.DowsampledSampleRateHzEditField.Value);
            if downsamplerate>app.EWapp.Mainapp.Data.Info.NativeSamplingRate
                msgbox("Error: Can not be higher than native samplerate!")
                return;
            end

            %% Determine for which kind of event channels data is present. First one that has data is plotted
            if strcmp(app.EWapp.Mainapp.Data.Info.RecordingType,"IntanDat") || strcmp(app.EWapp.Mainapp.Data.Info.RecordingType,"IntanRHD")
                if ~isempty(app.EWapp.EventInfo)
                    fields = fieldnames(app.EWapp.EventInfo);
                    foundchannel = 0;
                    if strcmp(app.FileTypeDropDown.Value,"Digital Inputs")
                        for i = 1:length(fields)
                            if strcmp(fields{i},"DIChannel")
                                Fieldnametopass = fields{i};
                                foundchannel = 1;
                                break;
                            end
                        end
                    elseif strcmp(app.FileTypeDropDown.Value,"Analog Inputs")
                        for i = 1:length(fields)
                            if strcmp(fields{i},"ADCChannel")
                                Fieldnametopass = fields{i};
                                foundchannel = 1;
                                break;
                            end
                        end
                    elseif strcmp(app.FileTypeDropDown.Value,"AUX Inputs")
                        for i = 1:length(fields)
                            if strcmp(fields{i},"AUXChannel")
                                Fieldnametopass = fields{i};
                                foundchannel = 1;
                                break;
                            end
                        end
                    elseif strcmp(app.FileTypeDropDown.Value,"DIN Inputs")
                        for i = 1:length(fields)
                            if strcmp(fields{i},"DINChannel")
                                Fieldnametopass = fields{i};
                                foundchannel = 1;
                                break;
                            end
                        end
                    end

                    if foundchannel == 0
                        msgbox(strcat("No event data for ",app.FileTypeDropDown.Value," found"));
                        app.FileTypeDropDown.Value = event.PreviousValue;
                        return;
                    end
                    
                    Extract_Events_Module_Show_ChannelPlots(app.EWapp.Mainapp.Data,Fieldnametopass,app.EWapp.Path,app,app.EWapp.RHDAllChannelData,"Initial",app.DowsampledSampleRateHzEditField.Value,app.Mainapp.executableFolder,app.FileTypeDropDown_3.Value);
                                      
                else
                    msgbox("No Event Channel for Selection available!");
                end

            elseif strcmp(app.EWapp.Mainapp.Data.Info.RecordingType,"Open Ephys")
                
                %% Extract and Show Channel
                [app.EWapp.EventInfo,FileEndingsExist,FilePaths,texttoshow,Info] = Extract_Events_Module_Determine_Available_EventChannel(app.EWapp.Mainapp.Data,app.EWapp.Path,app.FileTypeDropDown.Value);
            
                Extract_Events_Module_Set_Up_Window(app.EWapp,app.EWapp.Mainapp.Data,app.EWapp.EventInfo,app.EWapp.Path,FilePaths,FileEndingsExist,texttoshow,"Initial",Info);
                                
                [stringArray] = Utility_Extract_Contents_of_Folder(app.EWapp.Path);
                FolderIndicieWithEphysData = [];
                for foldercontents = 1:length(stringArray)
                    if contains(stringArray(foldercontents),'Record Node')
                        if isfolder(strcat(app.EWapp.Path,'\',stringArray(foldercontents)))
                            FolderIndicieWithEphysData = [FolderIndicieWithEphysData,foldercontents];
                        end
                    end
                end

                AvailableNode = stringArray(FolderIndicieWithEphysData);

                if isempty(app.EWapp.EventInfo)
                    msgbox("Selected Node contains no events.");
                else
                    SelectedNode = [];
                    for i = 1:length(app.FileTypeDropDown.Items)
                        if strcmp(app.FileTypeDropDown.Items{i},app.FileTypeDropDown.Value)
                            SelectedNode = i;
                            break;
                        end
                    end

                    if isempty(SelectedNode)
                        msgbox("Selected Node contains no events.");
                    end
                    
                    % Input Channel Number
                    differentEventnames = unique(app.EWapp.EventInfo{1}.line);
                
                    Nodenr = find(AvailableNode==app.FileTypeDropDown.Value);
                    OpenEphysEventInfo.RecordNode = Nodenr;
                    OpenEphysEventInfo.LineNumbers = differentEventnames;
                    OpenEphysEventInfo.SelectedLineNumbers = app.FileTypeDropDown_2.Value;
                    OpenEphysEventInfo.SelectedLineNode = SelectedNode;
                    OpenEphysEventInfo.Info = Info;

                    Extract_Events_Module_Show_ChannelPlots(app.EWapp.Mainapp.Data,OpenEphysEventInfo,app.EWapp.Path,app,app.EWapp.RHDAllChannelData,"Initial",app.DowsampledSampleRateHzEditField.Value,app.Mainapp.executableFolder,app.FileTypeDropDown_3.Value);
                end

            elseif strcmp(app.EWapp.Mainapp.Data.Info.RecordingType,"Neuralynx")

                FilesIndex = {};
                [stringarray] = Utility_Extract_Contents_of_Folder(app.EWapp.Path);
                FilesIndex = endsWith(stringarray, ".nev");

                if sum(FilesIndex)==0
                    ChannelPlot = 0; 
                    msgbox("No Input Channel found in selected folder");
                    return;
                end

                Filename = strcat(app.EWapp.Path,'\',stringarray{FilesIndex==1});
                
                [event] = Extract_Events_Module_Extract_Events_Neuralynx(Filename,app.EWapp.Path);
                
                % Just Select samples of the eventtypoe selected
                eventcell = {event.type};
                selectedfielytypeindicies = zeros(1,length({event.type}))+1;
                for i = 1:length({event.type})
                    if strcmp(eventcell{i},app.FileTypeDropDown.Value)
                        selectedfielytypeindicies(i) = 1;
                    else
                        selectedfielytypeindicies(i) = 0;
                    end
                end
            
                Eventsamples = double(cell2mat({event.sample}));
                Eventsamples(selectedfielytypeindicies==0) = [];

                if ~isempty(Eventsamples) 
                    for i = 1:length(Eventsamples)
                        app.EWapp.EventInfo.Samples{1}(i) = double(Eventsamples(i));
                    end
                    % Event 1 always sample 1
                    if app.EWapp.EventInfo.Samples{1}(1) == 1
                        app.EWapp.EventInfo.Samples{1}(1) = [];
                        msgbox("Warning: First sample is always 1 ans gets deleted.");
                    end

                    Zerosamples = app.EWapp.EventInfo.Samples{1} == 0;
                
                    if sum(Zerosamples) > 0
                        app.EWapp.EventInfo.Samples{1}(Zerosamples==1) = [];
                        msgbox("Warning: Indice of zero detected and deleted.")
                    end
                else
                     app.EWapp.EventInfo.Samples = [];
                     msgbox("Warning: No event samples found");
                     return;
                end

                Extract_Events_Module_Show_ChannelPlots(app.EWapp.Mainapp.Data,app.EWapp.EventInfo,app.EWapp.Path,app,app.EWapp.RHDAllChannelData,"Initial",app.DowsampledSampleRateHzEditField.Value,app.Mainapp.executableFolder,app.FileTypeDropDown_3.Value);
                     
            end
            
        end

        % Value changed function: FileTypeDropDown_3
        function FileTypeDropDown_3ValueChanged(app, event)
            cla(app.UIAxes);
            %% Determine for which kind of event channels data is present. First one that has data is plotted
            if strcmp(app.EWapp.Mainapp.Data.Info.RecordingType,"IntanDat") || strcmp(app.EWapp.Mainapp.Data.Info.RecordingType,"IntanRHD")
                if ~isempty(app.EWapp.EventInfo)
                    fields = fieldnames(app.EWapp.EventInfo);
                    foundchannel = 0;
                    if strcmp(app.FileTypeDropDown.Value,"Digital Inputs")
                        for i = 1:length(fields)
                            if strcmp(fields{i},"DIChannel")
                                Fieldnametopass = fields{i};
                                foundchannel = 1;
                                break;
                            end
                        end
                    elseif strcmp(app.FileTypeDropDown.Value,"Analog Inputs")
                        for i = 1:length(fields)
                            if strcmp(fields{i},"ADCChannel")
                                Fieldnametopass = fields{i};
                                foundchannel = 1;
                                break;
                            end
                        end
                    elseif strcmp(app.FileTypeDropDown.Value,"AUX Inputs")
                        for i = 1:length(fields)
                            if strcmp(fields{i},"AUXChannel")
                                Fieldnametopass = fields{i};
                                foundchannel = 1;
                                break;
                            end
                        end
                    elseif strcmp(app.FileTypeDropDown.Value,"DIN Inputs")
                        for i = 1:length(fields)
                            if strcmp(fields{i},"DINChannel")
                                Fieldnametopass = fields{i};
                                foundchannel = 1;
                                break;
                            end
                        end
                    end

                    if foundchannel == 0
                        msgbox(strcat("No event data for ",app.FileTypeDropDown.Value," found"));
                        app.FileTypeDropDown.Value = event.PreviousValue;
                        return;
                    end
                    
                    Extract_Events_Module_Show_ChannelPlots(app.EWapp.Mainapp.Data,Fieldnametopass,app.EWapp.Path,app,app.EWapp.RHDAllChannelData,"Initial",app.DowsampledSampleRateHzEditField.Value,app.Mainapp.executableFolder,app.FileTypeDropDown_3.Value);
                                      
                else
                    msgbox("No Event Channel for Selection available!");
                end

            elseif strcmp(app.EWapp.Mainapp.Data.Info.RecordingType,"Open Ephys")
                
                %% Extract and Show Channel
                [app.EWapp.EventInfo,FileEndingsExist,FilePaths,texttoshow,Info] = Extract_Events_Module_Determine_Available_EventChannel(app.EWapp.Mainapp.Data,app.EWapp.Path,app.FileTypeDropDown.Value);
            
                Extract_Events_Module_Set_Up_Window(app.EWapp,app.EWapp.Mainapp.Data,app.EWapp.EventInfo,app.EWapp.Path,FilePaths,FileEndingsExist,texttoshow,"Initial",Info);
                                
                [stringArray] = Utility_Extract_Contents_of_Folder(app.EWapp.Path);
                FolderIndicieWithEphysData = [];
                for foldercontents = 1:length(stringArray)
                    if contains(stringArray(foldercontents),'Record Node')
                        if isfolder(strcat(app.EWapp.Path,'\',stringArray(foldercontents)))
                            FolderIndicieWithEphysData = [FolderIndicieWithEphysData,foldercontents];
                        end
                    end
                end

                AvailableNode = stringArray(FolderIndicieWithEphysData);

                if isempty(app.EWapp.EventInfo)
                    msgbox("Selected Node contains no events.");
                else
                    SelectedNode = [];
                    for i = 1:length(app.FileTypeDropDown.Items)
                        if strcmp(app.FileTypeDropDown.Items{i},app.FileTypeDropDown.Value)
                            SelectedNode = i;
                            break;
                        end
                    end

                    if isempty(SelectedNode)
                        msgbox("Selected Node contains no events.");
                    end
                    
                    % Input Channel Number
                    differentEventnames = unique(app.EWapp.EventInfo{1}.line);
                
                    Nodenr = find(AvailableNode==app.FileTypeDropDown.Value);
                    OpenEphysEventInfo.RecordNode = Nodenr;
                    OpenEphysEventInfo.LineNumbers = differentEventnames;
                    OpenEphysEventInfo.SelectedLineNumbers = app.FileTypeDropDown_2.Value;
                    OpenEphysEventInfo.SelectedLineNode = SelectedNode;
                    OpenEphysEventInfo.Info = Info;

                    Extract_Events_Module_Show_ChannelPlots(app.EWapp.Mainapp.Data,OpenEphysEventInfo,app.EWapp.Path,app,app.EWapp.RHDAllChannelData,"Initial",app.DowsampledSampleRateHzEditField.Value,app.Mainapp.executableFolder,app.FileTypeDropDown_3.Value);
                end

            elseif strcmp(app.EWapp.Mainapp.Data.Info.RecordingType,"Neuralynx")

                FilesIndex = {};
                [stringarray] = Utility_Extract_Contents_of_Folder(app.EWapp.Path);
                FilesIndex = endsWith(stringarray, ".nev");

                if sum(FilesIndex)==0
                    ChannelPlot = 0; 
                    msgbox("No Input Channel found in selected folder");
                    return;
                end

                Filename = strcat(app.EWapp.Path,'\',stringarray{FilesIndex==1});
                
                [event] = Extract_Events_Module_Extract_Events_Neuralynx(Filename,app.EWapp.Path);
                
                % Just Select samples of the eventtypoe selected
                eventcell = {event.type};
                selectedfielytypeindicies = zeros(1,length({event.type}))+1;
                for i = 1:length({event.type})
                    if strcmp(eventcell{i},app.FileTypeDropDown.Value)
                        selectedfielytypeindicies(i) = 1;
                    else
                        selectedfielytypeindicies(i) = 0;
                    end
                end
            
                Eventsamples = double(cell2mat({event.sample}));
                Eventsamples(selectedfielytypeindicies==0) = [];

                if ~isempty(Eventsamples) 
                    for i = 1:length(Eventsamples)
                        app.EWapp.EventInfo.Samples{1}(i) = double(Eventsamples(i));
                    end
                    % Event 1 always sample 1
                    if app.EWapp.EventInfo.Samples{1}(1) == 1
                        app.EWapp.EventInfo.Samples{1}(1) = [];
                        msgbox("Warning: First sample is always 1 ans gets deleted.");
                    end

                    Zerosamples = app.EWapp.EventInfo.Samples{1} == 0;
                
                    if sum(Zerosamples) > 0
                        app.EWapp.EventInfo.Samples{1}(Zerosamples==1) = [];
                        msgbox("Warning: Indice of zero detected and deleted.")
                    end
                else
                     app.EWapp.EventInfo.Samples = [];
                     msgbox("Warning: No event samples found");
                     return;
                end

                Extract_Events_Module_Show_ChannelPlots(app.EWapp.Mainapp.Data,app.EWapp.EventInfo,app.EWapp.Path,app,app.EWapp.RHDAllChannelData,"Initial",app.DowsampledSampleRateHzEditField.Value,app.Mainapp.executableFolder,app.FileTypeDropDown_3.Value);
                     
            end
            
        end

        % Close request function: Show_Input_Channel_WindowUIFigure
        function Show_Input_Channel_WindowUIFigureCloseRequest(app, event)
            app.EWapp.ShowChannelPlotWindow = [];
            delete(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.Show_Input_Channel_WindowUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {585, 585};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Show_Input_Channel_WindowUIFigure = uifigure('Visible', 'off');
            app.Show_Input_Channel_WindowUIFigure.Name = 'Show_Input_Channel_Window';
            app.Show_Input_Channel_WindowUIFigure.Icon = 'Logo.png';
            app.Show_Input_Channel_WindowUIFigure.CloseRequestFcn = createCallbackFcn(app, @Show_Input_Channel_WindowUIFigureCloseRequest, true);
            app.Show_Input_Channel_WindowUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.Show_Input_Channel_WindowUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PlotOptionsPanel = uipanel(app.RightPanel);
            app.PlotOptionsPanel.Title = 'Plot Options';
            app.FileTypeDropDown = uidropdown(app.PlotOptionsPanel);
            app.FileTypeDropDown.Items = {'Digital Inputs', 'Analog Inputs', 'AUX Inputs', 'DIN Inputs'};
            app.FileTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @FileTypeDropDownValueChanged, true);
            app.FileTypeDropDown.Value = 'Digital Inputs';
            app.SelectInputChannelTypeLabel = uilabel(app.PlotOptionsPanel);
            app.SelectInputChannelTypeLabel.Text = {'Select Input Channel'; ' Type'};
            app.FileTypeDropDown_2 = uidropdown(app.PlotOptionsPanel);
            app.FileTypeDropDown_2.Items = {};
            app.FileTypeDropDown_2.ValueChangedFcn = createCallbackFcn(app, @FileTypeDropDown_2ValueChanged, true);
            app.FileTypeDropDown_2.Value = {};
            app.SelectInputChannelNumberLabel = uilabel(app.PlotOptionsPanel);
            app.SelectInputChannelNumberLabel.Text = {'Select Input Channel'; ' Number'};
            app.DowsampledSampleRateHzEditFieldLabel = uilabel(app.PlotOptionsPanel);
            app.DowsampledSampleRateHzEditFieldLabel.Text = {'Dowsampled'; 'Sample Rate [Hz]'};
            app.DowsampledSampleRateHzEditField = uieditfield(app.PlotOptionsPanel, 'text');
            app.DowsampledSampleRateHzEditField.ValueChangedFcn = createCallbackFcn(app, @DowsampledSampleRateHzEditFieldValueChanged, true);
            app.FileTypeDropDown_3 = uidropdown(app.PlotOptionsPanel);
            app.FileTypeDropDown_3.Items = {'State = 1', 'State = 0'};
            app.FileTypeDropDown_3.ValueChangedFcn = createCallbackFcn(app, @FileTypeDropDown_3ValueChanged, true);
            app.FileTypeDropDown_3.Value = 'State = 1';
            app.StatestoPlotonlyOpenEphysLabel = uilabel(app.PlotOptionsPanel);
            app.StatestoPlotonlyOpenEphysLabel.Text = {'States to Plot'; '(only Open Ephys)'};
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodLabel = uilabel(app.RightPanel);
            app.NeuromodLabel.Text = 'Neuromod';
            app.ShowAllInputChannelLabel = uilabel(app.RightPanel);
            app.ShowAllInputChannelLabel.Text = 'Show All Input Channel';
            app.CloseButton = uibutton(app.RightPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.Show_Input_Channel_WindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Show_Event_Channel_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.Show_Input_Channel_WindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.Show_Input_Channel_WindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Show_Input_Channel_WindowUIFigure)
        end
    end
end