classdef Import_Events_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImportEventsWindowUIFigure      matlab.ui.Figure
        ShowExampleFilesMenu            matlab.ui.container.Menu
        txtMenu                         matlab.ui.container.Menu
        OpenEditorMenu                  matlab.ui.container.Menu
        ShowFileMenu                    matlab.ui.container.Menu
        csvMenu                         matlab.ui.container.Menu
        OpenEditorMenu_2                matlab.ui.container.Menu
        ShowFileMenu_2                  matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        RightPanel                      matlab.ui.container.Panel
        ImportSettingsPanel             matlab.ui.container.Panel
        Panel                           matlab.ui.container.Panel
        EventNamescommaseparatedLabel   matlab.ui.control.Label
        InputChannelSelectionEditField_2  matlab.ui.control.EditField
        EventTypeDropDown               matlab.ui.control.DropDown
        EventTypeDropDownLabel          matlab.ui.control.Label
        NumberofEventChannelLabel       matlab.ui.control.Label
        InputChannelSelectionEditField  matlab.ui.control.EditField
        FilePathLabel                   matlab.ui.control.Label
        PlotEventDataButton             matlab.ui.control.Button
        TakeasnewEventDataButton        matlab.ui.control.Button
        SelectFilecsvortxtButton        matlab.ui.control.Button
        FileContentsLabel               matlab.ui.control.Label
        TextArea_2                      matlab.ui.control.TextArea
        TextArea                        matlab.ui.control.TextArea
        Image                           matlab.ui.control.Image
        ImportEventsWindowLabel         matlab.ui.control.Label
        ExtractEventRelatedDataPanel    matlab.ui.container.Panel
        InformationLabel                matlab.ui.control.Label
        TextArea_3                      matlab.ui.control.TextArea
        ExtractEventRelatedDataButton   matlab.ui.control.Button
        Panel_2                         matlab.ui.container.Panel
        EventChanneltoUseDropDown       matlab.ui.control.DropDown
        EventChanneltoUseDropDownLabel  matlab.ui.control.Label
        DatatoUseDropDown               matlab.ui.control.DropDown
        DatatoUseDropDownLabel          matlab.ui.control.Label
        TimeWindowAfterEventssEditField  matlab.ui.control.EditField
        TimeWindowAfterEventssEditFieldLabel  matlab.ui.control.Label
        TimeWindowBeforeEventssEditField  matlab.ui.control.EditField
        TimeWindowBeforeEventssEditFieldLabel  matlab.ui.control.Label
        CloseButton                     matlab.ui.control.Button
        NeuromodLabel                   matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Mainapp = [] % Description
        Path = [];
        EventInfo = [];
        ShowChannelPlotWindow = [];
        TimearoundEvent = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            % Moce this window in the middle of thescreen
            movegui(app.ImportEventsWindowUIFigure, 'center');

            app.Mainapp = Mainapp;

            app.Path = [];
            
            if ~isfield(app.Mainapp.Data,'Events')
                app.ExtractEventRelatedDataPanel.Enable = "off";
            else
                app.ExtractEventRelatedDataPanel.Enable = "on";
            end

            %% Initiate Event Extraction Panel -- Basic Parameters
            app.TextArea.Value = "Please select a .csv or .txt file containing your event/TTL data in samples. The fields on the left are automatically populated. After selecting a file, you can change the event/TTL channel names.";

            app.TextArea_2.Value = ["Info: Save event/TTL samples in a single column. For multiple event/TTL channel, save multiple data columns in the file you import, one for each event.";"Each row MUST begin with a string saying 'Event Nr 1:','Event Nr 2:' and so on for each event/TTL channel. These MUST be followed by the event indices in samples, separated by a comma. See the 'Show Example Files' tab on top of this window for example files.";"In the 'Import Settings' panel you can specify the number of event channel in the selected file as well as the name for each event/TTL channel displayed in this GUI."];
            
            %% Initiate Event Related Data Panel

            texttoshow = {};
            if ~isfield(app.Mainapp.Data,'Events') 
                app.Panel_2.Enable = "off";
                app.ExtractEventRelatedDataButton.Enable = "off";
                app.TextArea_3.Value = "No event channel data extracted. Please first extract events in the panel above.";
            else
                app.Panel_2.Enable = "on";
                app.ExtractEventRelatedDataButton.Enable = "on";

                for i = 1:length(app.Mainapp.Data.Events)
                    texttoshow{i} = convertStringsToChars(app.Mainapp.Data.Info.EventChannelNames{i});
                end
    
                app.EventChanneltoUseDropDown.Items = texttoshow;
    
                texttoshow = {};
                n = 1;
                if isfield(app.Mainapp.Data,'Raw')
                    texttoshow{n} = 'Raw';
                    n = n+1;
                end
    
                if isfield(app.Mainapp.Data,'Preprocessed')
                    texttoshow{n} = 'Preprocessed';
                end
    
                app.DatatoUseDropDown.Items = texttoshow;
    
                if ~isfield(app.Mainapp.Data,'Events')
                    app.TextArea_3.Value = "No events extracted. Please extract events first in the above panel.";
                else

                    if isfield(app.Mainapp.Data,'EventRelatedData')
                        app.TextArea_3.Value = "Event Related Data found.";
        
                        app.TextArea_3.Value = [app.TextArea_3.Value;"Number of Events:";size(app.Mainapp.Data.EventRelatedData,2);"Channel:";app.Mainapp.Data.Info.EventRelatedDataChannel;"Time Range:";app.Mainapp.Data.Info.EventRelatedDataTimeRange;"Data Type:";app.Mainapp.Data.Info.EventRelatedDataType];
                    else
                        app.TextArea_3.Value = "No Event Related Data found. Use this panel to extract event related data.";
                    end
                end
            end

        end

        % Callback function: not associated with a component
        function FileTypeDropDownValueChanged(app, event)
      
        end

        % Button pushed function: SelectFilecsvortxtButton
        function SelectFilecsvortxtButtonPushed(app, event)
            if ~isempty(app.EventInfo)
                app.EventInfo = [];
            end
            %% Get user selcted a csv or txt
            % Prompt the user to select a folder
            % Open file explorer to select a .csv or .txt file
            [filename, pathname] = uigetfile({'*.csv;*.txt', 'CSV and Text Files (*.csv, *.txt)'}, ...
                                             'Select a CSV or TXT file');
            
            % Check if the user canceled the dialog
            if isequal(filename, 0)
                disp('Selection canceled.');
                return
            else
                NewPath = fullfile(pathname, filename);
            end

            % Read file lines
            lines = readlines(NewPath);
                        
            % Initialize cell array to hold event vectors
            Testdata = {};
            
            % Loop through each line
            for i = 1:numel(lines)
                line = strtrim(lines(i));  % Remove leading/trailing whitespace
                if startsWith(line, "Event Nr")
                    % Split by commas
                    parts = split(line, ',');
                    pointindicie = find(parts{1}==':');
                    
                    parts{1} = parts{1}(pointindicie+1:end);
                   
                    % Extract numeric part (skip the first element which is the label)
                    numericValues = str2double(parts(1:end));
                    
                    % Store in events cell
                    Testdata{end+1} = numericValues;
                end
            end

            for i = 1:length(Testdata)
                Testdata{i} = Testdata{i}';
            end
            
            % Initialize a string array to hold each block of output
            outputLines = strings(0, 1);  % Empty column vector of strings
            
            % Loop through all events
            for idx = 1:numel(Testdata)
                % Get the data
                data = Testdata{idx};
            
                % Convert to comma-separated string
                dataStr = strjoin(string(data), ', ');
            
                % Add header and data as separate lines
                outputLines(end+1) = sprintf('Event Nr: %d', idx);
                outputLines(end+1) = dataStr;
            end

            if isempty(Testdata)
                Errormessage = ("Error: File did not contain expected content. Make sure event/TTL data starts with 'Event Nr 1:' followed by numbers (without spaces) separated with a comma. See the 'Show Example Files' tab on top of this window.");
                app.TextArea_2.Value = Errormessage;
                app.TextArea.Value = strcat("Please select another file.");
                return
            end

            app.InputChannelSelectionEditField_2.Value = '';

            for i = 1:length(Testdata)
                if i ~= 1
                    app.InputChannelSelectionEditField_2.Value = [app.InputChannelSelectionEditField_2.Value,',',strcat('Event TTL Nr. ', num2str(i))];
                else
                    app.InputChannelSelectionEditField_2.Value = [app.InputChannelSelectionEditField_2.Value,strcat('Event TTL Nr. ', num2str(i))];
                end
            end
            
            app.EventInfo.NumberEvents = length(Testdata);
            app.EventInfo.TempEvents = Testdata;
            app.EventInfo.EventNames = app.InputChannelSelectionEditField_2.Value;

            app.InputChannelSelectionEditField.Value = num2str(app.EventInfo.NumberEvents);
            app.TextArea_2.Value = outputLines;
            
            app.Path = NewPath;

            app.TextArea.Value = strcat("File ",app.Path ," succesfully loaded.");


        end

        % Button pushed function: PlotEventDataButton
        function PlotEventDataButtonPushed(app, event)
            
            if isempty(app.EventInfo)
                msgbox("Error: No Event Channel found.");
                return;
            end

            %% For Single Intan RHD Files
            if strcmp(app.Mainapp.Data.Info.RecordingType,"IntanRHD") && app.ExtractedRHDEventsFlag == 0
            
                [RhdFilePaths] = LoadIntanRHDFiles(app.Path);
                LastDashIndex = find(RhdFilePaths == '\');
                RHDPath = RhdFilePaths(1:LastDashIndex(end));
                RHDFiles = RhdFilePaths(LastDashIndex(end)+1:end);

                app.TextArea_2.Value = "Extracting Event Input Channel from RHD file";

                [~,app.RHDAllChannelData.board_adc_data,~,app.RHDAllChannelData.board_dig_in_data,~,~,~,app.RHDAllChannelData.aux_input_data,~] = Intan_RHD2000_Data_Extraction (RHDFiles,RHDPath,"Extracting",app.TextArea_2);
            
                app.ExtractedRHDEventsFlag = 1;

                app.TextArea_2.Value = "Event Channel Extraction to show Event Channel Plots finished.";

            end

            app.ShowChannelPlotWindow = Show_Event_Channel_Window(app.Mainapp,app);
        end

        % Button pushed function: TakeasnewEventDataButton
        function TakeasnewEventDataButtonPushed(app, event)
            if ~isfield(app.EventInfo,'TempEvents')
                app.TextArea_2.Value = "Error: No valid file containing events selected!";
                return
            end

            if isempty(app.EventInfo.TempEvents)
                app.TextArea_2.Value = "Error: No valid file containing events selected!";
                return
            end

            [app.Mainapp.Data,EventChannelDropDown] = Import_Events_Add_Imported_Events(app.Mainapp.Data,app.EventInfo);
            
            if ~isempty(EventChannelDropDown)  % If events succesfully extracted
                app.Mainapp.EventChannelDropDown.Items = EventChannelDropDown;
                app.Mainapp.CurrentEventChannel = 1;
                app.Mainapp.PlotEvents = "Non";
                pause(0.2);
            
                if length(app.Mainapp.DropDown_2.Items)==2
                    if strcmp(app.Mainapp.DropDown_2.Items{2},'Spikes')
                        Placeholder = {};
                        app.Mainapp.DropDown_2.Items = Placeholder;
                        app.Mainapp.DropDown_2.Items{1} = 'Non';
                        app.Mainapp.DropDown_2.Items{2} = 'Events';
                        app.Mainapp.DropDown_2.Items{3} = 'Spikes';
                    else
                        Placeholder = {};
                        app.Mainapp.DropDown_2.Items = Placeholder;
                        app.Mainapp.DropDown_2.Items{1} = 'Non';
                        app.Mainapp.DropDown_2.Items{2} = 'Events';
                    end
                elseif length(app.Mainapp.DropDown_2.Items)==3
                    Placeholder = {};
                    app.Mainapp.DropDown_2.Items = Placeholder;
                    app.Mainapp.DropDown_2.Items{1} = 'Non';
                    app.Mainapp.DropDown_2.Items{2} = 'Events';
                    app.Mainapp.DropDown_2.Items{3} = 'Spikes';
                else
                    Placeholder = {};
                    app.Mainapp.DropDown_2.Items = Placeholder;
                    app.Mainapp.DropDown_2.Items{1} = 'Non';
                    app.Mainapp.DropDown_2.Items{2} = 'Events';
                end

                app.Mainapp.EventChannelDropDown.Enable = "on";
            
                %% Activate and Initiate Event related data extraction panel
            
                app.ExtractEventRelatedDataPanel.Enable = "on";
            
                for i = 1:length(app.Mainapp.Data.Events)
                    texttoshow{i} = convertStringsToChars(app.Mainapp.Data.Info.EventChannelNames{i});
                end
            
                app.EventChanneltoUseDropDown.Items = texttoshow;
            
                texttoshow = {};
                n = 1;
                if isfield(app.Mainapp.Data,'Raw')
                    texttoshow{n} = 'Raw';
                    n = n+1;
                end
            
                if isfield(app.Mainapp.Data,'Preprocessed')
                    texttoshow{n} = 'Preprocessed';
                end
            
                app.DatatoUseDropDown.Items = texttoshow;
            
                app.TextArea_3.Value = "No Event Related Data found. Use this panel to extract event related data.";
                
               
                app.Panel_2.Enable = "on";
                app.ExtractEventRelatedDataButton.Enable = "on";
                
            else % If no events extracted
                                        
                PlaceHolder = {};
                app.Mainapp.EventChannelDropDown.Items = PlaceHolder;
                app.Mainapp.EventChannelDropDown.Enable = "off";

                if length(app.Mainapp.DropDown_2.Items)==2
                    if strcmp(app.Mainapp.DropDown_2.Items{2},'Spikes')
                        Placeholder = {};
                        app.Mainapp.DropDown_2.Items = Placeholder;
                        app.Mainapp.DropDown_2.Items{1} = 'Non';
                        app.Mainapp.DropDown_2.Items{2} = 'Spikes';
                    else
                        Placeholder = {};
                        app.Mainapp.DropDown_2.Items = Placeholder;
                        app.Mainapp.DropDown_2.Items{1} = 'Non';
                    end
                else
                    Placeholder = {};
                    app.Mainapp.DropDown_2.Items = Placeholder;
                    app.Mainapp.DropDown_2.Items{1} = 'Non';
                end

                if ~isempty(app.Mainapp.EventChannelDropDown.Items)
                    app.Mainapp.EventChannelDropDown.Value = app.Mainapp.EventChannelDropDown.Items{1};
                end
                
                app.Mainapp.PlotEvents = "Non";

                app.Panel_2.Enable = "off";
                app.ExtractEventRelatedDataButton.Enable = "off";
                app.TextArea_3.Value = "No events extracted. Please extract events first in the panel above.";
                
            end
            

            if ~isfield(app.Mainapp.Data,'Events')
                app.ExtractEventRelatedDataPanel.Enable = "off";
            else
                app.ExtractEventRelatedDataPanel.Enable = "on";
            end
            %% Show new Info Header entries in main window

            Utility_Show_Info_Loaded_Data(app.Mainapp);

            Organize_Reset_Main_Plot(app.Mainapp,0,1,0,1,0,"Initial");
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            try
                app.Mainapp.ImportEventTTLWindow = [];
                delete(app)
            catch 
                delete(app)
            end
        end

        % Value changed function: EventChanneltoUseDropDown
        function EventChanneltoUseDropDownValueChanged(app, event)
            value = app.EventChanneltoUseDropDown.Value;
            EventChannelNr = [];
            for i = 1:length(app.Mainapp.Data.Info.EventChannelNames)
                if strcmp(app.EventChanneltoUseDropDown.Value,app.Mainapp.Data.Info.EventChannelNames{i})
                    EventChannelNr = i;
                end
            end

            app.TextArea_3.Value = ["Number of events for Channel ",app.EventChanneltoUseDropDown.Value," ",num2str(length(app.Mainapp.Data.Events{EventChannelNr}))];

        end

        % Value changed function: TimeWindowBeforeEventssEditField
        function TimeWindowBeforeEventssEditFieldValueChanged(app, event)
            [app.TimeWindowBeforeEventssEditField.Value] = Utility_SimpleCheckInputs(app.TimeWindowBeforeEventssEditField.Value,"One",'0.2',0,0);
            value = app.TimeWindowBeforeEventssEditField.Value;
            app.TimearoundEvent(1) = str2double(value);
        end

        % Value changed function: TimeWindowAfterEventssEditField
        function TimeWindowAfterEventssEditFieldValueChanged(app, event)
            [app.TimeWindowAfterEventssEditField.Value] = Utility_SimpleCheckInputs(app.TimeWindowAfterEventssEditField.Value,"One",'0.5',0,0);
            value = app.TimeWindowAfterEventssEditField.Value;
            app.TimearoundEvent(2) = str2double(value);
        end

        % Button pushed function: ExtractEventRelatedDataButton
        function ExtractEventRelatedDataButtonPushed(app, event)
            [app.Mainapp.Data,app.TimearoundEvent] = Event_Module_Extract_Event_Related_Data(app.Mainapp.Data,app.EventChanneltoUseDropDown.Value,app.TimeWindowBeforeEventssEditField.Value,app.TimeWindowAfterEventssEditField.Value,app.DatatoUseDropDown.Value);

            if isfield(app.Mainapp.Data,'EventRelatedData') 
                if ~isempty(app.Mainapp.Data.EventRelatedData)
                     app.TextArea_3.Value = strcat("Success. Event Related Data can now be Analysed. Found ",num2str(size(app.Mainapp.Data.EventRelatedData,1))," Channel, ",num2str(size(app.Mainapp.Data.EventRelatedData,2))," Events and ",num2str(size(app.Mainapp.Data.EventRelatedData,3))," Time Points.");
                     Utility_Show_Info_Loaded_Data(app.Mainapp);
                else
                    app.TextArea_3.Value = "No Event Related Data could be Extracted.";
                end
            end
        end

        % Callback function: not associated with a component
        function AnalogThresholdVEditFieldValueChanged(app, event)
            [app.AnalogThresholdVEditField.Value] = Utility_SimpleCheckInputs(app.AnalogThresholdVEditField.Value,"One",'1',0,1);
        end

        % Value changed function: InputChannelSelectionEditField
        function InputChannelSelectionEditFieldValueChanged(app, event)
            commafind = find(app.InputChannelSelectionEditField.Value==',');

            if isempty(commafind)
                SelectedChannel = str2double(app.InputChannelSelectionEditField.Value);
                if isnan(SelectedChannel)
                    msgbox("Wrong format. Enter either single number or comma seperated numbers!")
                    app.InputChannelSelectionEditField.Value = event.PreviousValue;
                    return;
                end
            else
                strArray = strsplit(app.InputChannelSelectionEditField.Value, ',');
                numericVector = str2double(strArray);
                if sum(isnan(numericVector))>0
                    msgbox("Wrong format. Enter either single number or comma seperated numbers!")
                    app.InputChannelSelectionEditField.Value = event.PreviousValue;
                    return;
                else
                    SelectedChannel = numericVector;
                end
            end
            
            if ~strcmp(app.Mainapp.Data.Info.RecordingType,"Spike2")

                commafind = find(app.maxInputSelection==',');
                if isempty(commafind)
                    MaxSelectedChannel = str2double(app.maxInputSelection);
                else
                    strArray = strsplit(app.maxInputSelection, ',');
                    numericVector = str2double(strArray);
                    MaxSelectedChannel = numericVector;
                end

                if ~all(ismember(SelectedChannel,MaxSelectedChannel))
                    msgbox("Channel Selection exceeds max number of found channel! Value is autoadjusted to previous one.")
                    app.InputChannelSelectionEditField.Value = event.PreviousValue;
                    return;

                end

            end
            
        end

        % Close request function: ImportEventsWindowUIFigure
        function ImportEventsWindowUIFigureCloseRequest(app, event)
            if ~isempty(app.ShowChannelPlotWindow)
                delete(app.ShowChannelPlotWindow);
            end
            
            app.Mainapp.EventExtractionWindow = [];
            delete(app);
            
        end

        % Value changed function: InputChannelSelectionEditField_2
        function InputChannelSelectionEditField_2ValueChanged(app, event)
            
            app.EventInfo.EventNames = app.InputChannelSelectionEditField_2.Value;
        end

        % Menu selected function: txtMenu
        function txtMenuSelected(app, event)
            
        end

        % Menu selected function: OpenEditorMenu
        function OpenEditorMenuSelected(app, event)
            filetoopen = strcat(app.Mainapp.executableFolder,"/Modules/MISC/Variables (do not edit)/Example_Events.txt");
            edit(filetoopen)
        end

        % Menu selected function: OpenEditorMenu_2
        function OpenEditorMenu_2Selected(app, event)
            filetoopen = strcat(app.Mainapp.executableFolder,"/Modules/MISC/Variables (do not edit)/Example_Events.csv");
            edit(filetoopen)
        end

        % Menu selected function: ShowFileMenu
        function ShowFileMenuSelected(app, event)
            filetoopen = strcat(app.Mainapp.executableFolder,'\Modules\MISC\Variables (do not edit)\Example_Events.txt');
            
            % Use the system command 'explorer' with the '/select,' argument to highlight the file
            system(['explorer /select, "', filetoopen, '"']);

            msgbox("Opened the folder and highlighted the file in windows file explorer!")
        end

        % Menu selected function: ShowFileMenu_2
        function ShowFileMenu_2Selected(app, event)
            filetoopen = strcat(app.Mainapp.executableFolder,'\Modules\MISC\Variables (do not edit)\Example_Events.csv');
            
            % Use the system command 'explorer' with the '/select,' argument to highlight the file
            system(['explorer /select, "', filetoopen, '"']);

            msgbox("Opened the folder and highlighted the file in windows file explorer!")
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ImportEventsWindowUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {687, 687};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImportEventsWindowUIFigure = uifigure('Visible', 'off');
            app.ImportEventsWindowUIFigure.Name = 'Import Events Window';
            app.ImportEventsWindowUIFigure.Icon = 'Logo.png';
            app.ImportEventsWindowUIFigure.CloseRequestFcn = createCallbackFcn(app, @ImportEventsWindowUIFigureCloseRequest, true);
            app.ImportEventsWindowUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.ShowExampleFilesMenu = uimenu(app.ImportEventsWindowUIFigure);
            app.ShowExampleFilesMenu.Text = '   Show Example Files   ';
            app.txtMenu = uimenu(app.ShowExampleFilesMenu);
            app.txtMenu.MenuSelectedFcn = createCallbackFcn(app, @txtMenuSelected, true);
            app.txtMenu.Text = '.txt';
            app.OpenEditorMenu = uimenu(app.txtMenu);
            app.OpenEditorMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenEditorMenuSelected, true);
            app.OpenEditorMenu.Text = 'Open Editor';
            app.ShowFileMenu = uimenu(app.txtMenu);
            app.ShowFileMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowFileMenuSelected, true);
            app.ShowFileMenu.Text = 'Show File';
            app.csvMenu = uimenu(app.ShowExampleFilesMenu);
            app.csvMenu.Text = '.csv';
            app.OpenEditorMenu_2 = uimenu(app.csvMenu);
            app.OpenEditorMenu_2.MenuSelectedFcn = createCallbackFcn(app, @OpenEditorMenu_2Selected, true);
            app.OpenEditorMenu_2.Text = 'Open Editor';
            app.ShowFileMenu_2 = uimenu(app.csvMenu);
            app.ShowFileMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ShowFileMenu_2Selected, true);
            app.ShowFileMenu_2.Text = 'Show File';
            app.GridLayout = uigridlayout(app.ImportEventsWindowUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.NeuromodLabel = uilabel(app.RightPanel);
            app.NeuromodLabel.Text = 'Neuromod';
            app.CloseButton = uibutton(app.RightPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.ExtractEventRelatedDataPanel = uipanel(app.RightPanel);
            app.ExtractEventRelatedDataPanel.Title = 'Extract Event Related Data';
            app.Panel_2 = uipanel(app.ExtractEventRelatedDataPanel);
            app.TimeWindowBeforeEventssEditFieldLabel = uilabel(app.Panel_2);
            app.TimeWindowBeforeEventssEditFieldLabel.Text = {'Time Window '; 'Before Events [s]'};
            app.TimeWindowBeforeEventssEditField = uieditfield(app.Panel_2, 'text');
            app.TimeWindowBeforeEventssEditField.ValueChangedFcn = createCallbackFcn(app, @TimeWindowBeforeEventssEditFieldValueChanged, true);
            app.TimeWindowBeforeEventssEditField.Value = '0.2';
            app.TimeWindowAfterEventssEditFieldLabel = uilabel(app.Panel_2);
            app.TimeWindowAfterEventssEditFieldLabel.Text = {'Time Window '; 'After Events [s]'};
            app.TimeWindowAfterEventssEditField = uieditfield(app.Panel_2, 'text');
            app.TimeWindowAfterEventssEditField.ValueChangedFcn = createCallbackFcn(app, @TimeWindowAfterEventssEditFieldValueChanged, true);
            app.TimeWindowAfterEventssEditField.Value = '0.7';
            app.DatatoUseDropDownLabel = uilabel(app.Panel_2);
            app.DatatoUseDropDownLabel.Text = 'Data to Use';
            app.DatatoUseDropDown = uidropdown(app.Panel_2);
            app.DatatoUseDropDown.Items = {''};
            app.DatatoUseDropDown.Value = '';
            app.EventChanneltoUseDropDownLabel = uilabel(app.Panel_2);
            app.EventChanneltoUseDropDownLabel.Text = {'Event Channel'; 'to Use'};
            app.EventChanneltoUseDropDown = uidropdown(app.Panel_2);
            app.EventChanneltoUseDropDown.Items = {''};
            app.EventChanneltoUseDropDown.ValueChangedFcn = createCallbackFcn(app, @EventChanneltoUseDropDownValueChanged, true);
            app.EventChanneltoUseDropDown.Value = '';
            app.ExtractEventRelatedDataButton = uibutton(app.ExtractEventRelatedDataPanel, 'push');
            app.ExtractEventRelatedDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractEventRelatedDataButtonPushed, true);
            app.ExtractEventRelatedDataButton.Text = {'Extract Event '; 'Related Data'};
            app.TextArea_3 = uitextarea(app.ExtractEventRelatedDataPanel);
            app.InformationLabel = uilabel(app.ExtractEventRelatedDataPanel);
            app.InformationLabel.Text = 'Information';
            app.ImportEventsWindowLabel = uilabel(app.RightPanel);
            app.ImportEventsWindowLabel.Text = 'Import Events Window';
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = 'Logo.png';
            app.TextArea = uitextarea(app.RightPanel);
            app.TextArea_2 = uitextarea(app.RightPanel);
            app.FileContentsLabel = uilabel(app.RightPanel);
            app.FileContentsLabel.Text = 'File Contents';
            app.SelectFilecsvortxtButton = uibutton(app.RightPanel, 'push');
            app.SelectFilecsvortxtButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFilecsvortxtButtonPushed, true);
            app.SelectFilecsvortxtButton.Text = {'Select File '; '(.csv or .txt)'};
            app.TakeasnewEventDataButton = uibutton(app.RightPanel, 'push');
            app.TakeasnewEventDataButton.ButtonPushedFcn = createCallbackFcn(app, @TakeasnewEventDataButtonPushed, true);
            app.TakeasnewEventDataButton.Text = {'Take as new'; 'Event Data'};
            app.PlotEventDataButton = uibutton(app.RightPanel, 'push');
            app.PlotEventDataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotEventDataButtonPushed, true);
            app.PlotEventDataButton.Text = {'Plot Event'; 'Data'};
            app.FilePathLabel = uilabel(app.RightPanel);
            app.FilePathLabel.Text = 'File Path';
            app.ImportSettingsPanel = uipanel(app.RightPanel);
            app.ImportSettingsPanel.Title = 'Import Settings';
            app.Panel = uipanel(app.ImportSettingsPanel);
            app.InputChannelSelectionEditField = uieditfield(app.Panel, 'text');
            app.InputChannelSelectionEditField.ValueChangedFcn = createCallbackFcn(app, @InputChannelSelectionEditFieldValueChanged, true);
            app.InputChannelSelectionEditField.Editable = 'off';
            app.NumberofEventChannelLabel = uilabel(app.Panel);
            app.NumberofEventChannelLabel.Text = {'Number of Event'; 'Channel'};
            app.EventTypeDropDownLabel = uilabel(app.Panel);
            app.EventTypeDropDownLabel.Text = 'Event Type';
            app.EventTypeDropDown = uidropdown(app.Panel);
            app.EventTypeDropDown.Items = {'Event Onset', 'Event Offset'};
            app.EventTypeDropDown.Value = 'Event Onset';
            app.InputChannelSelectionEditField_2 = uieditfield(app.Panel, 'text');
            app.InputChannelSelectionEditField_2.ValueChangedFcn = createCallbackFcn(app, @InputChannelSelectionEditField_2ValueChanged, true);
            app.EventNamescommaseparatedLabel = uilabel(app.Panel);
            app.EventNamescommaseparatedLabel.Text = 'Event Names (comma separated)';
            % Show the figure after all components are created
            app.ImportEventsWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Import_Events_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ImportEventsWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ImportEventsWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImportEventsWindowUIFigure)
        end
    end
end