classdef Preprocessing_Events_Artefact_Rejection_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ArtefactRejectionWindowUIFigure  matlab.ui.Figure
        CloseButton                    matlab.ui.control.Button
        NeuromodToolboxLabel           matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        ArtefactRejectionWindowLabel   matlab.ui.control.Label
        CheckButton                    matlab.ui.control.Button
        SaveButton                     matlab.ui.control.Button
        Panel                          matlab.ui.container.Panel
        Slider                         matlab.ui.control.Slider
        ChannelSpacingLabel            matlab.ui.control.Label
        MethodDropDown                 matlab.ui.control.DropDown
        MethodLabel                    matlab.ui.control.Label
        ChannelofInterestDropDown      matlab.ui.control.DropDown
        ChannelofInterestJustforERPPlotLabel  matlab.ui.control.Label
        ChannelSelectionEditField      matlab.ui.control.EditField
        ChannelSelectionLabel          matlab.ui.control.Label
        TrialsEditField                matlab.ui.control.EditField
        TrialsEditFieldLabel           matlab.ui.control.Label
        TimeWindowtoectractsEditField  matlab.ui.control.EditField
        TimeWindowtoectractLabel       matlab.ui.control.Label
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        % Add a property to store the names of the vectors
        TextHandle = []; % Store the handle to the displayed text
        TrialsTextHandle = []; % Store the handle to the displayed text
        Mainapp = [];
        EventTime = [];
        colorMap = [];
        EventsRelatedApp = [];
    end

    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp, EventsRelatedApp)
            movegui(app.ArtefactRejectionWindowUIFigure, 'center');
            
            app.Mainapp = Mainapp;
            app.EventsRelatedApp = EventsRelatedApp;

            %% Fill GUI fields
            texttoshow = {};
            for nchannel = 1:size(app.Mainapp.Data.EventRelatedData,1)
                texttoshow{nchannel} = num2str(nchannel);
            end

            app.ChannelofInterestDropDown.Items = texttoshow;

            app.ChannelSelectionEditField.Value = strcat("1,",num2str(size(app.Mainapp.Data.EventRelatedData,1)));

            if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                app.TrialsEditField.Value = strcat("1,",num2str(size(app.Mainapp.Data.PreprocessedEventRelatedData,2)));
            else
                app.TrialsEditField.Value = strcat("1,",num2str(size(app.Mainapp.Data.EventRelatedData,2)));
            end

            EventRelatedDataTimeRange = [];
                 
            spaceindicie = find(app.Mainapp.Data.Info.EventRelatedDataTimeRange == ' ');
            EventRelatedDataTimeRange(1) = str2double(app.Mainapp.Data.Info.EventRelatedDataTimeRange(1:spaceindicie));
            EventRelatedDataTimeRange(2) = str2double(app.Mainapp.Data.Info.EventRelatedDataTimeRange(spaceindicie+1:end));


            if strcmp(app.Mainapp.Data.Info.EventRelatedDataType,'Raw')
                app.EventTime = 0-EventRelatedDataTimeRange(1):1/app.Mainapp.Data.Info.NativeSamplingRate:EventRelatedDataTimeRange(2);
            else
                if isfield(app.Mainapp.Data.Info,"DownsampleFactor")
                    app.EventTime = 0-EventRelatedDataTimeRange(1):1/app.Mainapp.Data.Info.DownsampledSampleRate:EventRelatedDataTimeRange(2);
                else
                    app.EventTime = 0-EventRelatedDataTimeRange(1):1/app.Mainapp.Data.Info.NativeSamplingRate:EventRelatedDataTimeRange(2);
                end
            end

            app.UIAxes.FontSize = 10;
            app.UIAxes_2.FontSize = 10;
            app.UIAxes2.FontSize = 10;

            xlabel(app.UIAxes,'Time [s]');
            ylabel(app.UIAxes,'Signal [mV]');
            xlim(app.UIAxes,[app.EventTime(1) app.EventTime(end)]);

            xlabel(app.UIAxes_2,'Time [s]');
            ylabel(app.UIAxes_2,'Signal [mV]');
            xlim(app.UIAxes_2,[app.EventTime(1) app.EventTime(end)]);

            xlabel(app.UIAxes2, 'Time [s]');
            xlim(app.UIAxes2, [app.EventTime(1),app.EventTime(end)]);
            app.UIAxes2.YTick = [];
            app.UIAxes2.YLabel = [];

            %% Get GUi inputs
           
            if isempty(app.ChannelSelectionEditField.Value)
                msgbox("Error: No Channel Selected ");
                return;
            end

            %% Get Timewindow and trial information from GUI
            if isempty(app.TimeWindowtoectractsEditField.Value)
                TimeWindin(1,1) = app.EventTime(floor(length(app.EventTime)/3));
                TimeWindin(1,2) = app.EventTime(floor(length(app.EventTime)/3)*2);
            else
                indicesep = find(app.TimeWindowtoectractsEditField.Value == ',');
                TimeWindin(1,1) = str2double(app.TimeWindowtoectractsEditField.Value(1:indicesep(1)-1));
                TimeWindin(1,2) = str2double(app.TimeWindowtoectractsEditField.Value(indicesep+1:end));
            end

            %Channel
            indicesep = find(app.ChannelSelectionEditField.Value == ',');
            Channel(1,1) = str2double(app.ChannelSelectionEditField.Value(1:indicesep(1)-1));
            Channel(1,2) = str2double(app.ChannelSelectionEditField.Value(indicesep+1:end));
            if Channel(1,2) > size(app.Mainapp.Data.EventRelatedData,1)
                msgbox("Last channel bigger than channel number. Autosetting to all channel");
                Channel(1,2) = size(app.Mainapp.Data.EventRelatedData,1);
                app.ChannelSelectionEditField.Value = strcat("1,",num2str(size(app.Mainapp.Data.EventRelatedData,1)));
            end

            indicesep = find(app.TrialsEditField.Value == ',');
            Trials(1,1) = str2double(app.TrialsEditField.Value(1:indicesep(1)-1));
            Trials(1,2) = str2double(app.TrialsEditField.Value(indicesep+1:end));
       
            %ChanneltoPlot = str2double(app.ChannelofInterestDropDown.Value);
            
            %% Extract Data and Plot

            app.colorMap = app.Mainapp.tempcolorMapset;

            if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                [~,~,~] = Preprocessing_Events_Reject_and_Plot_Artefacts(app.Mainapp.Data.PreprocessedEventRelatedData,Channel,Trials,app.EventTime,TimeWindin,app.UIAxes,app.UIAxes_2,app.UIAxes2,app.ChannelofInterestDropDown.Value,app.Slider.Value,app.MethodDropDown.Value,'DeleteandPlot',app.colorMap,'All');
            else
                [~,~,~] = Preprocessing_Events_Reject_and_Plot_Artefacts(app.Mainapp.Data.EventRelatedData,Channel,Trials,app.EventTime,TimeWindin,app.UIAxes,app.UIAxes_2,app.UIAxes2,app.ChannelofInterestDropDown.Value,app.Slider.Value,app.MethodDropDown.Value,'DeleteandPlot',app.colorMap,'All');
            end

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            %% Get GUi inputs
           
            if isempty(app.ChannelSelectionEditField.Value)
                msgbox("Error: No Channel Selected ");
                return;
            end

            [app.ChannelSelectionEditField,app.TimeWindowtoectractsEditField,app.TrialsEditField,app.EventTime] = Preprocessing_Events_Check_Inputs_Artefact_Rejection(app.Mainapp.Data,app.ChannelSelectionEditField,app.TimeWindowtoectractsEditField,app.TrialsEditField,app.EventTime);

            %% Get Timewindow and trial information from GUI
            if isempty(app.TimeWindowtoectractsEditField.Value)
                TimeWindin(1,1) = app.EventTime(floor(length(app.EventTime)/3));
                TimeWindin(1,2) = app.EventTime(floor(length(app.EventTime)/3)*2);
            else
                indicesep = find(app.TimeWindowtoectractsEditField.Value == ',');
                TimeWindin(1,1) = str2double(app.TimeWindowtoectractsEditField.Value(1:indicesep(1)-1));
                TimeWindin(1,2) = str2double(app.TimeWindowtoectractsEditField.Value(indicesep+1:end));
            end

            %Channel
            indicesep = find(app.ChannelSelectionEditField.Value == ',');
            Channel(1,1) = str2double(app.ChannelSelectionEditField.Value(1:indicesep(1)-1));
            Channel(1,2) = str2double(app.ChannelSelectionEditField.Value(indicesep+1:end));
            if Channel(1,2) > size(app.Mainapp.Data.EventRelatedData,1)
                msgbox("Last channel bigger than channel number. Autosetting to all channel");
                Channel(1,2) = size(app.Mainapp.Data.EventRelatedData,1);
                app.ChannelSelectionEditField.Value = strcat("1,",num2str(size(app.Mainapp.Data.EventRelatedData,1)));
            end

            indicesep = find(app.TrialsEditField.Value == ',');
            Trials(1,1) = str2double(app.TrialsEditField.Value(1:indicesep(1)-1));
            Trials(1,2) = str2double(app.TrialsEditField.Value(indicesep+1:end));
       
            %ChanneltoPlot = str2double(app.ChannelofInterestDropDown.Value);
            
            %% Extract Data and Plot

            app.colorMap = app.Mainapp.tempcolorMapset;

            if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                [app.Mainapp.Data.PreprocessedEventRelatedData,~,~] = Preprocessing_Events_Reject_and_Plot_Artefacts(app.Mainapp.Data.PreprocessedEventRelatedData,Channel,Trials,app.EventTime,TimeWindin,app.UIAxes,app.UIAxes_2,app.UIAxes2,app.ChannelofInterestDropDown.Value,app.Slider.Value,app.MethodDropDown.Value,'Interpolating',app.colorMap,'NoInterpolationPlot');
            else
                [app.Mainapp.Data.PreprocessedEventRelatedData,~,~] = Preprocessing_Events_Reject_and_Plot_Artefacts(app.Mainapp.Data.EventRelatedData,Channel,Trials,app.EventTime,TimeWindin,app.UIAxes,app.UIAxes_2,app.UIAxes2,app.ChannelofInterestDropDown.Value,app.Slider.Value,app.MethodDropDown.Value,'Interpolating',app.colorMap,'NoInterpolationPlot');
            end

            [app.Mainapp.Data] = Preprocessing_Events_Add_Preprocessing_Info(app.Mainapp.Data,'Artefact Rejection',Channel,Trials,TimeWindin);

            Utility_Show_Info_Loaded_Data(app.Mainapp);

            Preprocessing_Events_PopulateInfoText(app.EventsRelatedApp.TextArea,app.Mainapp.Data);

            msgbox("Artefact rejection succesfully applied to dataset and saved.");
        end

        % Button pushed function: CheckButton
        function CheckButtonPushed(app, event)

            %% Get correct animal and session based on input
           
            if isempty(app.ChannelSelectionEditField.Value)
                msgbox("Error: No Channel Selected ");
                return;
            end

            [app.ChannelSelectionEditField,app.TimeWindowtoectractsEditField,app.TrialsEditField,app.EventTime] = Preprocessing_Events_Check_Inputs_Artefact_Rejection(app.Mainapp.Data,app.ChannelSelectionEditField,app.TimeWindowtoectractsEditField,app.TrialsEditField,app.EventTime);
                            
            %% Get Timewindow and trial information from GUI
            if isempty(app.TimeWindowtoectractsEditField.Value)
                TimeWindin(1,1) = app.EventTime(floor(length(app.EventTime)/3));
                TimeWindin(1,2) = app.EventTime(floor(length(app.EventTime)/3)*2);
            else
                indicesep = find(app.TimeWindowtoectractsEditField.Value == ',');
                TimeWindin(1,1) = str2double(app.TimeWindowtoectractsEditField.Value(1:indicesep(1)-1));
                TimeWindin(1,2) = str2double(app.TimeWindowtoectractsEditField.Value(indicesep+1:end));
            end

            %Channel
            indicesep = find(app.ChannelSelectionEditField.Value == ',');
            Channel(1,1) = str2double(app.ChannelSelectionEditField.Value(1:indicesep(1)-1));
            Channel(1,2) = str2double(app.ChannelSelectionEditField.Value(indicesep+1:end));
            if Channel(1,2) > size(app.Mainapp.Data.EventRelatedData,1)
                msgbox("Last channel bigger than channel number. Autosetting to all channel");
                Channel(1,2) = size(app.Mainapp.Data.EventRelatedData,1);
                app.ChannelSelectionEditField.Value = strcat("1,",num2str(size(app.Mainapp.Data.EventRelatedData,1)));
            end

            indicesep = find(app.TrialsEditField.Value == ',');
            Trials(1,1) = str2double(app.TrialsEditField.Value(1:indicesep(1)-1));
            Trials(1,2) = str2double(app.TrialsEditField.Value(indicesep+1:end));
       
            %ChanneltoPlot = str2double(app.ChannelofInterestDropDown.Value);
            
            %% Extract Data and Plot
            if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                [~,~,~] = Preprocessing_Events_Reject_and_Plot_Artefacts(app.Mainapp.Data.PreprocessedEventRelatedData,Channel,Trials,app.EventTime,TimeWindin,app.UIAxes,app.UIAxes_2,app.UIAxes2,app.ChannelofInterestDropDown.Value,app.Slider.Value,app.MethodDropDown.Value,'Interpolating',app.colorMap,'InterpolationPlot');
            else
                [~,~,~] = Preprocessing_Events_Reject_and_Plot_Artefacts(app.Mainapp.Data.EventRelatedData,Channel,Trials,app.EventTime,TimeWindin,app.UIAxes,app.UIAxes_2,app.UIAxes2,app.ChannelofInterestDropDown.Value,app.Slider.Value,app.MethodDropDown.Value,'Interpolating',app.colorMap,'InterpolationPlot');
            end

        end

        % Value changed function: TimeWindowtoectractsEditField
        function TimeWindowtoectractsEditFieldValueChanged(app, event)
            %% Get GUi inputs
           
            if isempty(app.ChannelSelectionEditField.Value)
                msgbox("Error: No Channel Selected ");
                return;
            end

            [app.ChannelSelectionEditField,app.TimeWindowtoectractsEditField,app.TrialsEditField,app.EventTime] = Preprocessing_Events_Check_Inputs_Artefact_Rejection(app.Mainapp.Data,app.ChannelSelectionEditField,app.TimeWindowtoectractsEditField,app.TrialsEditField,app.EventTime);

            %% Get Timewindow and trial information from GUI
            if isempty(app.TimeWindowtoectractsEditField.Value)
                TimeWindin(1,1) = app.EventTime(floor(length(app.EventTime)/3));
                TimeWindin(1,2) = app.EventTime(floor(length(app.EventTime)/3)*2);
            else
                indicesep = find(app.TimeWindowtoectractsEditField.Value == ',');
                TimeWindin(1,1) = str2double(app.TimeWindowtoectractsEditField.Value(1:indicesep(1)-1));
                TimeWindin(1,2) = str2double(app.TimeWindowtoectractsEditField.Value(indicesep+1:end));
            end

            %Channel
            indicesep = find(app.ChannelSelectionEditField.Value == ',');
            Channel(1,1) = str2double(app.ChannelSelectionEditField.Value(1:indicesep(1)-1));
            Channel(1,2) = str2double(app.ChannelSelectionEditField.Value(indicesep+1:end));
            if Channel(1,2) > size(app.Mainapp.Data.EventRelatedData,1)
                msgbox("Last channel bigger than channel number. Autosetting to all channel");
                Channel(1,2) = size(app.Mainapp.Data.EventRelatedData,1);
                app.ChannelSelectionEditField.Value = strcat("1,",num2str(size(app.Mainapp.Data.EventRelatedData,1)));
            end

            indicesep = find(app.TrialsEditField.Value == ',');
            Trials(1,1) = str2double(app.TrialsEditField.Value(1:indicesep(1)-1));
            Trials(1,2) = str2double(app.TrialsEditField.Value(indicesep+1:end));
       
            %ChanneltoPlot = str2double(app.ChannelofInterestDropDown.Value);
            
            %% Extract Data and Plot
            if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                [~,~,~] = Preprocessing_Events_Reject_and_Plot_Artefacts(app.Mainapp.Data.PreprocessedEventRelatedData,Channel,Trials,app.EventTime,TimeWindin,app.UIAxes,app.UIAxes_2,app.UIAxes2,app.ChannelofInterestDropDown.Value,app.Slider.Value,app.MethodDropDown.Value,'DeleteandPlot',app.colorMap,'ERP');
            else
                [~,~,~] = Preprocessing_Events_Reject_and_Plot_Artefacts(app.Mainapp.Data.EventRelatedData,Channel,Trials,app.EventTime,TimeWindin,app.UIAxes,app.UIAxes_2,app.UIAxes2,app.ChannelofInterestDropDown.Value,app.Slider.Value,app.MethodDropDown.Value,'DeleteandPlot',app.colorMap,'ERP');
            end

        end

        % Value changed function: TrialsEditField
        function TrialsEditFieldValueChanged(app, event)
            %% Get GUi inputs
           
            if isempty(app.ChannelSelectionEditField.Value)
                msgbox("Error: No Channel Selected ");
                return;
            end

            [app.ChannelSelectionEditField,app.TimeWindowtoectractsEditField,app.TrialsEditField,app.EventTime] = Preprocessing_Events_Check_Inputs_Artefact_Rejection(app.Mainapp.Data,app.ChannelSelectionEditField,app.TimeWindowtoectractsEditField,app.TrialsEditField,app.EventTime);

            %% Get Timewindow and trial information from GUI
            if isempty(app.TimeWindowtoectractsEditField.Value)
                TimeWindin(1,1) = app.EventTime(floor(length(app.EventTime)/3));
                TimeWindin(1,2) = app.EventTime(floor(length(app.EventTime)/3)*2);
            else
                indicesep = find(app.TimeWindowtoectractsEditField.Value == ',');
                TimeWindin(1,1) = str2double(app.TimeWindowtoectractsEditField.Value(1:indicesep(1)-1));
                TimeWindin(1,2) = str2double(app.TimeWindowtoectractsEditField.Value(indicesep+1:end));
            end

            %Channel
            indicesep = find(app.ChannelSelectionEditField.Value == ',');
            Channel(1,1) = str2double(app.ChannelSelectionEditField.Value(1:indicesep(1)-1));
            Channel(1,2) = str2double(app.ChannelSelectionEditField.Value(indicesep+1:end));
            if Channel(1,2) > size(app.Mainapp.Data.EventRelatedData,1)
                msgbox("Last channel bigger than channel number. Autosetting to all channel");
                Channel(1,2) = size(app.Mainapp.Data.EventRelatedData,1);
                app.ChannelSelectionEditField.Value = strcat("1,",num2str(size(app.Mainapp.Data.EventRelatedData,1)));
            end

            indicesep = find(app.TrialsEditField.Value == ',');
            Trials(1,1) = str2double(app.TrialsEditField.Value(1:indicesep(1)-1));
            Trials(1,2) = str2double(app.TrialsEditField.Value(indicesep+1:end));
                   
            %% Extract Data and Plot
            if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                [~,~,~] = Preprocessing_Events_Reject_and_Plot_Artefacts(app.Mainapp.Data.PreprocessedEventRelatedData,Channel,Trials,app.EventTime,TimeWindin,app.UIAxes,app.UIAxes_2,app.UIAxes2,app.ChannelofInterestDropDown.Value,app.Slider.Value,app.MethodDropDown.Value,'DeleteandPlot',app.colorMap,'All');
            else
                [~,~,~] = Preprocessing_Events_Reject_and_Plot_Artefacts(app.Mainapp.Data.EventRelatedData,Channel,Trials,app.EventTime,TimeWindin,app.UIAxes,app.UIAxes_2,app.UIAxes2,app.ChannelofInterestDropDown.Value,app.Slider.Value,app.MethodDropDown.Value,'DeleteandPlot',app.colorMap,'All');
            end
        end

        % Value changed function: ChannelSelectionEditField
        function ChannelSelectionEditFieldValueChanged(app, event)
          [app.ChannelSelectionEditField,app.TimeWindowtoectractsEditField,app.TrialsEditField,app.EventTime] = Preprocessing_Events_Check_Inputs_Artefact_Rejection(app.Mainapp.Data,app.ChannelSelectionEditField,app.TimeWindowtoectractsEditField,app.TrialsEditField,app.EventTime);
               
        
        end

        % Value changed function: ChannelofInterestDropDown
        function ChannelofInterestDropDownValueChanged(app, event)
            %% Check if ChanneltoPlot is within Channelrange selected
            %Channel
            indicesep = find(app.ChannelSelectionEditField.Value == ',');
            Channel(1,1) = str2double(app.ChannelSelectionEditField.Value(1:indicesep(1)-1));
            Channel(1,2) = str2double(app.ChannelSelectionEditField.Value(indicesep+1:end));
            if str2double(app.ChannelofInterestDropDown.Value) > Channel(1,2)
                msgbox("Warning: Selected Channel not within specified channelrange! Channel to plot got autoadjusted");
                app.ChannelofInterestDropDown.Value = num2str(Channel(1,2));
            end

            %% Get GUi inputs
           
            if isempty(app.ChannelSelectionEditField.Value)
                msgbox("Error: No Channel Selected ");
                return;
            end

            [app.ChannelSelectionEditField,app.TimeWindowtoectractsEditField,app.TrialsEditField,app.EventTime] = Preprocessing_Events_Check_Inputs_Artefact_Rejection(app.Mainapp.Data,app.ChannelSelectionEditField,app.TimeWindowtoectractsEditField,app.TrialsEditField,app.EventTime);
 
            %% Get Timewindow and trial information from GUI
            if isempty(app.TimeWindowtoectractsEditField.Value)
                TimeWindin(1,1) = app.EventTime(floor(length(app.EventTime)/3));
                TimeWindin(1,2) = app.EventTime(floor(length(app.EventTime)/3)*2);
            else
                indicesep = find(app.TimeWindowtoectractsEditField.Value == ',');
                TimeWindin(1,1) = str2double(app.TimeWindowtoectractsEditField.Value(1:indicesep(1)-1));
                TimeWindin(1,2) = str2double(app.TimeWindowtoectractsEditField.Value(indicesep+1:end));
            end

            %Channel
            indicesep = find(app.ChannelSelectionEditField.Value == ',');
            Channel(1,1) = str2double(app.ChannelSelectionEditField.Value(1:indicesep(1)-1));
            Channel(1,2) = str2double(app.ChannelSelectionEditField.Value(indicesep+1:end));
            if Channel(1,2) > size(app.Mainapp.Data.EventRelatedData,1)
                msgbox("Last channel bigger than channel number. Autosetting to all channel");
                Channel(1,2) = size(app.Mainapp.Data.EventRelatedData,1);
                app.ChannelSelectionEditField.Value = strcat("1,",num2str(size(app.Mainapp.Data.EventRelatedData,1)));
            end

            indicesep = find(app.TrialsEditField.Value == ',');
            Trials(1,1) = str2double(app.TrialsEditField.Value(1:indicesep(1)-1));
            Trials(1,2) = str2double(app.TrialsEditField.Value(indicesep+1:end));
            
            %% Extract Data and Plot
            if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                [~,~,~] = Preprocessing_Events_Reject_and_Plot_Artefacts(app.Mainapp.Data.PreprocessedEventRelatedData,Channel,Trials,app.EventTime,TimeWindin,app.UIAxes,app.UIAxes_2,app.UIAxes2,app.ChannelofInterestDropDown.Value,app.Slider.Value,app.MethodDropDown.Value,'DeleteandPlot',app.colorMap,'ERP');
            else
                [~,~,~] = Preprocessing_Events_Reject_and_Plot_Artefacts(app.Mainapp.Data.EventRelatedData,Channel,Trials,app.EventTime,TimeWindin,app.UIAxes,app.UIAxes_2,app.UIAxes2,app.ChannelofInterestDropDown.Value,app.Slider.Value,app.MethodDropDown.Value,'DeleteandPlot',app.colorMap,'ERP');
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Callback function
        function SliderValueChanged(app, event)
   
            
        end

        % Value changed function: Slider
        function SliderValueChanged2(app, event)
            %% Get GUi inputs
           
            if isempty(app.ChannelSelectionEditField.Value)
                msgbox("Error: No Channel Selected ");
                return;
            end

            [app.ChannelSelectionEditField,app.TimeWindowtoectractsEditField,app.TrialsEditField,app.EventTime] = Preprocessing_Events_Check_Inputs_Artefact_Rejection(app.Mainapp.Data,app.ChannelSelectionEditField,app.TimeWindowtoectractsEditField,app.TrialsEditField,app.EventTime);
                            
            %% Get Timewindow and trial information from GUI
            if isempty(app.TimeWindowtoectractsEditField.Value)
                TimeWindin(1,1) = app.EventTime(floor(length(app.EventTime)/3));
                TimeWindin(1,2) = app.EventTime(floor(length(app.EventTime)/3)*2);
            else
                indicesep = find(app.TimeWindowtoectractsEditField.Value == ',');
                TimeWindin(1,1) = str2double(app.TimeWindowtoectractsEditField.Value(1:indicesep(1)-1));
                TimeWindin(1,2) = str2double(app.TimeWindowtoectractsEditField.Value(indicesep+1:end));
            end

            %Channel
            indicesep = find(app.ChannelSelectionEditField.Value == ',');
            Channel(1,1) = str2double(app.ChannelSelectionEditField.Value(1:indicesep(1)-1));
            Channel(1,2) = str2double(app.ChannelSelectionEditField.Value(indicesep+1:end));
            if Channel(1,2) > size(app.Mainapp.Data.EventRelatedData,1)
                msgbox("Last channel bigger than channel number. Autosetting to all channel");
                Channel(1,2) = size(app.Mainapp.Data.EventRelatedData,1);
                app.ChannelSelectionEditField.Value = strcat("1,",num2str(size(app.Mainapp.Data.EventRelatedData,1)));
            end

            indicesep = find(app.TrialsEditField.Value == ',');
            Trials(1,1) = str2double(app.TrialsEditField.Value(1:indicesep(1)-1));
            Trials(1,2) = str2double(app.TrialsEditField.Value(indicesep+1:end));
       
            %ChanneltoPlot = str2double(app.ChannelofInterestDropDown.Value);
            
            %% Extract Data and Plot
            if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                [~,~,~] = Preprocessing_Events_Reject_and_Plot_Artefacts(app.Mainapp.Data.PreprocessedEventRelatedData,Channel,Trials,app.EventTime,TimeWindin,app.UIAxes,app.UIAxes_2,app.UIAxes2,app.ChannelofInterestDropDown.Value,app.Slider.Value,app.MethodDropDown.Value,'DeleteandPlot',app.colorMap,'ERPAllChannel');
            else
                [~,~,~] = Preprocessing_Events_Reject_and_Plot_Artefacts(app.Mainapp.Data.EventRelatedData,Channel,Trials,app.EventTime,TimeWindin,app.UIAxes,app.UIAxes_2,app.UIAxes2,app.ChannelofInterestDropDown.Value,app.Slider.Value,app.MethodDropDown.Value,'DeleteandPlot',app.colorMap,'ERPAllChannel');
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ArtefactRejectionWindowUIFigure = uifigure('Visible', 'off');
            app.ArtefactRejectionWindowUIFigure.Name = 'Artefact Rejection Window';
            app.ArtefactRejectionWindowUIFigure.Icon = 'Logo.png';
            app.UIAxes = uiaxes(app.ArtefactRejectionWindowUIFigure);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.ArtefactRejectionWindowUIFigure);
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes2 = uiaxes(app.ArtefactRejectionWindowUIFigure);
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Panel = uipanel(app.ArtefactRejectionWindowUIFigure);
            app.TimeWindowtoectractLabel = uilabel(app.Panel);
            app.TimeWindowtoectractLabel.Text = {'Time Window of '; 'Artefact [from to]'};
            app.TimeWindowtoectractsEditField = uieditfield(app.Panel, 'text');
            app.TimeWindowtoectractsEditField.ValueChangedFcn = createCallbackFcn(app, @TimeWindowtoectractsEditFieldValueChanged, true);
            app.TrialsEditFieldLabel = uilabel(app.Panel);
            app.TrialsEditFieldLabel.Text = 'Trials';
            app.TrialsEditField = uieditfield(app.Panel, 'text');
            app.TrialsEditField.ValueChangedFcn = createCallbackFcn(app, @TrialsEditFieldValueChanged, true);
            app.ChannelSelectionLabel = uilabel(app.Panel);
            app.ChannelSelectionLabel.Text = {'Channel '; 'Selection'};
            app.ChannelSelectionEditField = uieditfield(app.Panel, 'text');
            app.ChannelSelectionEditField.ValueChangedFcn = createCallbackFcn(app, @ChannelSelectionEditFieldValueChanged, true);
            app.ChannelofInterestJustforERPPlotLabel = uilabel(app.Panel);
            app.ChannelofInterestJustforERPPlotLabel.Text = {'Channel to '; 'Plot'};
            app.ChannelofInterestDropDown = uidropdown(app.Panel);
            app.ChannelofInterestDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16'};
            app.ChannelofInterestDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelofInterestDropDownValueChanged, true);
            app.ChannelofInterestDropDown.Value = '1';
            app.MethodLabel = uilabel(app.Panel);
            app.MethodLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.Panel);
            app.MethodDropDown.Items = {'Linear Interpolation', 'NaN'};
            app.MethodDropDown.Value = 'Linear Interpolation';
            app.ChannelSpacingLabel = uilabel(app.Panel);
            app.ChannelSpacingLabel.Text = {'Channel '; 'Spacing'};
            app.Slider = uislider(app.Panel);
            app.Slider.Limits = [0.001 0.1];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged2, true);
            app.Slider.Value = 0.001;
            app.SaveButton = uibutton(app.ArtefactRejectionWindowUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = {'Save as new'; 'Dataset'};
            app.CheckButton = uibutton(app.ArtefactRejectionWindowUIFigure, 'push');
            app.CheckButton.ButtonPushedFcn = createCallbackFcn(app, @CheckButtonPushed, true);
            app.CheckButton.Text = {'Interpolated Artefact'; ' Time Window'};
            app.ArtefactRejectionWindowLabel = uilabel(app.ArtefactRejectionWindowUIFigure);
            app.ArtefactRejectionWindowLabel.Text = 'Artefact Rejection Window';
            app.Image = uiimage(app.ArtefactRejectionWindowUIFigure);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodToolboxLabel = uilabel(app.ArtefactRejectionWindowUIFigure);
            app.NeuromodToolboxLabel.Text = 'Neuromod Toolbox';
            app.CloseButton = uibutton(app.ArtefactRejectionWindowUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.ArtefactRejectionWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Preprocessing_Events_Artefact_Rejection_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ArtefactRejectionWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ArtefactRejectionWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ArtefactRejectionWindowUIFigure)
        end
    end
end