classdef Preprocessing_Events_Channel_Rejection_and_Interpolation_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ChannelRejectionWindowUIFigure  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        RightPanel                      matlab.ui.container.Panel
        CloseButton                     matlab.ui.control.Button
        ChannelSettingsPanel            matlab.ui.container.Panel
        RejectChannelFormat11or110EditField  matlab.ui.control.EditField
        InterpolateChannelFormat11or110EditFieldLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        ChannelInterpolationWindowLabel  matlab.ui.control.Label
        DataLineSpacingLabel            matlab.ui.control.Label
        Slider                          matlab.ui.control.Slider
        SaveasnewDatasetButton          matlab.ui.control.Button
        NeuromodLabel                   matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        ContextMenu                     matlab.ui.container.ContextMenu
        ChangeChannelSpacingLimitsMenu  matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        % Add a property to store the names of the vectors
        TextHandle = []; % Store the handle to the displayed text
        EventTime = []; % Store the handle to the displayed text
        Mainapp = [];
        EventsRelatedApp = [];
    end
        
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp, EventsRelatedApp)
            movegui(app.ChannelRejectionWindowUIFigure, 'center');

            app.Mainapp = Mainapp;
            app.EventsRelatedApp = EventsRelatedApp;

            %% Fill GUI fields
            
            if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 0
                app.RejectChannelFormat11or110EditField.Value = strcat(num2str(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel(1)),',',num2str(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel(1)));
            else
                ReversedActiveChannel = (str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)*str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)+1)-app.Mainapp.Data.Info.ProbeInfo.ActiveChannel;
                app.RejectChannelFormat11or110EditField.Value = strcat(num2str(ReversedActiveChannel(1)),',',num2str(ReversedActiveChannel(1)));
            end

            app.Slider.Limits = app.EventsRelatedApp.Mainapp.Slider.Limits;
            app.Slider.Value = app.EventsRelatedApp.Mainapp.Slider.Value;

            EventRelatedDataTimeRange = [];
                 
            spaceindicie = find(app.Mainapp.Data.Info.EventRelatedDataTimeRange == ' ');
            EventRelatedDataTimeRange(1) = str2double(app.Mainapp.Data.Info.EventRelatedDataTimeRange(1:spaceindicie(1)));
            EventRelatedDataTimeRange(2) = str2double(app.Mainapp.Data.Info.EventRelatedDataTimeRange(spaceindicie(1)+1:end));

            if strcmp(app.Mainapp.Data.Info.EventRelatedDataType,'Raw')
                app.EventTime = 0-EventRelatedDataTimeRange(1):1/app.Mainapp.Data.Info.NativeSamplingRate:EventRelatedDataTimeRange(2);
            else
                if isfield(app.Mainapp.Data.Info,"DownsampleFactor")
                    app.EventTime = 0-EventRelatedDataTimeRange(1):1/app.Mainapp.Data.Info.DownsampledSampleRate:EventRelatedDataTimeRange(2);
                else
                    app.EventTime = 0-EventRelatedDataTimeRange(1):1/app.Mainapp.Data.Info.NativeSamplingRate:EventRelatedDataTimeRange(2);
                end
            end

            app.UIAxes.FontSize = 10;
            app.UIAxes.YTick = [];
            app.UIAxes.YLabel = [];
            xlabel(app.UIAxes, 'Time [s]');

            %% Plot with standard values on startup

            %% Check ChannelSelection
            Input = app.RejectChannelFormat11or110EditField.Value;
            Error = 0;
            if isempty(Input) == 0 % If Trialselection field filled (empty = All Trials)
            
                CommaTest = find(Input == ',');
                
                if isempty(CommaTest) || ~isempty(find(Input == '.')) || length(CommaTest) ~= 1 || any(isletter(Input)) % If no Comma or Point
                    f = msgbox("Format Error for Channel Selection. Example Format: 1,10 or 1,1. Value change to standard");
                    app.RejectChannelFormat11or110EditField.Value = "1,1"; 
                    Error = 1;
                end
                
                if Error == 0
            
                    Inputvalue(1,1) = str2double(Input(1:CommaTest(1)-1));
                    Inputvalue(1,2) = str2double(Input(CommaTest+1:end));
                   
                    if isnan(Inputvalue(1)) || isnan(Inputvalue(2))
                        f = msgbox("Format Error for Channel Selection. Example Format: 1,10 or 1,1. Value change to standard");
                        app.RejectChannelFormat11or110EditField.Value = "1,1";
                        Error = 1;
                    end
            
                    if Error == 0
                        if Inputvalue(1) > Inputvalue(2)
                            f = msgbox("Format Error for Channel Selection. Example Format: 1,10 or 1,1. Value change to standard");
                            app.RejectChannelFormat11or110EditField.Value = "1,1";
                            Error = 1;
                        end
                        
                        if Inputvalue(1) <= 0 || Inputvalue(2) <= 0
                            f = msgbox("Format Error for Channel Selection. Example Format: 1,10 or 1,1. Value change to standard");
                            app.RejectChannelFormat11or110EditField.Value = "1,1"; 
                            Error = 1;
                        end
                    end
                end
            end

            %% Get Timewindow and trial information from GUI

            indicesep = find(app.RejectChannelFormat11or110EditField.Value == ',');
            RejectChannel(1,1) = str2double(app.RejectChannelFormat11or110EditField.Value(1:indicesep(1)-1));
            RejectChannel(1,2) = str2double(app.RejectChannelFormat11or110EditField.Value(indicesep+1:end));

            if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                [~] = Preprocessing_Events_Channel_Rejection(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.EventTime,RejectChannel,app.Slider.Value,app.UIAxes,"All",app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
            else
                [~] = Preprocessing_Events_Channel_Rejection(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.EventTime,RejectChannel,app.Slider.Value,app.UIAxes,"All",app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
            end
        end

        % Button pushed function: SaveasnewDatasetButton
        function SaveasnewDatasetButtonPushed(app, event)
            %% Check ChannelSelection
            Input = app.RejectChannelFormat11or110EditField.Value;
            Error = 0;
            if isempty(Input) == 0 % If Trialselection field filled (empty = All Trials)
            
                CommaTest = find(Input == ',');
                
                if isempty(CommaTest) || ~isempty(find(Input == '.')) || length(CommaTest) ~= 1 || any(isletter(Input)) % If no Comma or Point
                    f = msgbox("Format Error for Channel Selection. Example Format: 1,10 or 1,1. Value change to standard");
                    app.RejectChannelFormat11or110EditField.Value = "1,1"; 
                    Error = 1;
                end
                
                if Error == 0
            
                    Inputvalue(1,1) = str2double(Input(1:CommaTest(1)-1));
                    Inputvalue(1,2) = str2double(Input(CommaTest+1:end));
                   
                    if isnan(Inputvalue(1)) || isnan(Inputvalue(2))
                        f = msgbox("Format Error for Channel Selection. Example Format: 1,10 or 1,1. Value change to standard");
                        app.RejectChannelFormat11or110EditField.Value = "1,1";
                        Error = 1;
                    end
            
                    if Error == 0
                        if Inputvalue(1) > Inputvalue(2)
                            f = msgbox("Format Error for Channel Selection. Example Format: 1,10 or 1,1. Value change to standard");
                            app.RejectChannelFormat11or110EditField.Value = "1,1";
                            Error = 1;
                        end
                        
                        if Inputvalue(1) <= 0 || Inputvalue(2) <= 0
                            f = msgbox("Format Error for Channel Selection. Example Format: 1,10 or 1,1. Value change to standard");
                            app.RejectChannelFormat11or110EditField.Value = "1,1"; 
                            Error = 1;
                        end
                    end
                end
            end

            %% Get Timewindow and trial information from GUI

            indicesep = find(app.RejectChannelFormat11or110EditField.Value == ',');
            RejectChannel(1,1) = str2double(app.RejectChannelFormat11or110EditField.Value(1:indicesep(1)-1));
            RejectChannel(1,2) = str2double(app.RejectChannelFormat11or110EditField.Value(indicesep+1:end));

            if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                [app.Mainapp.Data.PreprocessedEventRelatedData] = Preprocessing_Events_Channel_Rejection(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.EventTime,RejectChannel,app.Slider.Value,app.UIAxes,"All",app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
            else
                [app.Mainapp.Data.PreprocessedEventRelatedData] = Preprocessing_Events_Channel_Rejection(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.EventTime,RejectChannel,app.Slider.Value,app.UIAxes,"All",app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
            end

            Trials = [1,size(app.Mainapp.Data.PreprocessedEventRelatedData,2)];
            
            [app.Mainapp.Data] = Preprocessing_Events_Add_Preprocessing_Info(app.Mainapp.Data,'Channel Rejection',RejectChannel,Trials,[]);

            Utility_Show_Info_Loaded_Data(app.Mainapp);

            Preprocessing_Events_PopulateInfoText(app.EventsRelatedApp.TextArea,app.Mainapp.Data);

            msgbox("Changes were succesfully applied to the dataset.");
        end

        % Value changed function: RejectChannelFormat11or110EditField
        function RejectChannelFormat11or110EditFieldValueChanged(app, event)
            if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 0
                StandardChannel = app.Mainapp.Data.Info.ProbeInfo.ActiveChannel(1);
            else
                ReversedActiveChannel = (str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)*str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)+1)-app.Mainapp.Data.Info.ProbeInfo.ActiveChannel;
                StandardChannel = ReversedActiveChannel(1);
            end

            [app.RejectChannelFormat11or110EditField.Value] = Utility_SimpleCheckInputs(app.RejectChannelFormat11or110EditField.Value,"Two",strcat(num2str(StandardChannel),',',num2str(StandardChannel)),0,0);

            commaindicie = find(app.RejectChannelFormat11or110EditField.Value==',');
            SelectedChannel(1) = str2double(app.RejectChannelFormat11or110EditField.Value(1:commaindicie(1)-1));
            SelectedChannel(2) = str2double(app.RejectChannelFormat11or110EditField.Value(commaindicie(1)+1:end));
            
            RejectChannel = SelectedChannel;

            % AllSelectedChannel = SelectedChannel(1):SelectedChannel(2);
            % SelectedChannelIndex = zeros(size(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel));
            % 
            % if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 0
            %     for i = 1:length(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel)
            %         if sum(AllSelectedChannel==app.Mainapp.Data.Info.ProbeInfo.ActiveChannel(i))==1
            %             SelectedChannelIndex(i) = 1;
            %         end
            %     end
            % else
            %     ReversedActiveChannel = (str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)*str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)+1)-app.Mainapp.Data.Info.ProbeInfo.ActiveChannel;
            %     for i = 1:length(ReversedActiveChannel)
            %         if sum(AllSelectedChannel==ReversedActiveChannel(i))==1
            %             SelectedChannelIndex(i) = 1;
            %         end
            %     end
            % end
            % 
            % if sum(SelectedChannelIndex)>0
            %     if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 1
            %         TemActiveChannel = flip(sort(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel));
            %         TempRejectChannel = app.Mainapp.Data.Info.ProbeInfo.ActiveChannel(logical(SelectedChannelIndex));
            %     else
            %         TempRejectChannel = app.Mainapp.Data.Info.ProbeInfo.ActiveChannel(logical(SelectedChannelIndex));
            %     end
            % else
            %     msgbox("None of the selected channel are defined as active channel.");
            %     if isprop(event,'PreviousValue')
            %         app.ChannelSelectionEditField.Value = event.PreviousValue;    
            %         return;
            %     end
            % end        
            % 
            % RejectChannel(1) = min(TempRejectChannel);
            % RejectChannel(2) = max(TempRejectChannel);

            app.UIAxes.NextPlot = "replace";
            cla(app.UIAxes);
            app.UIAxes.NextPlot = "add";
             
            %% Plot with standard values on startup
            if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                [~] = Preprocessing_Events_Channel_Rejection(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.EventTime,RejectChannel,app.Slider.Value,app.UIAxes,"All",app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
            else
                [~] = Preprocessing_Events_Channel_Rejection(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.EventTime,RejectChannel,app.Slider.Value,app.UIAxes,"All",app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
            end
        end

        % Callback function
        function ElectrodeofInterestDropDownValueChanged(app, event)
            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ChannelRejectionWindowUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {571, 571};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            %% Check ChannelSelection
            Input = app.RejectChannelFormat11or110EditField.Value;
            Error = 0;
            if isempty(Input) == 0 % If Trialselection field filled (empty = All Trials)
            
                CommaTest = find(Input == ',');
                
                if isempty(CommaTest) || ~isempty(find(Input == '.')) || length(CommaTest) ~= 1 || any(isletter(Input)) % If no Comma or Point
                    f = msgbox("Format Error for Channel Selection. Example Format: 1,10 or 1,1. Value change to standard");
                    app.RejectChannelFormat11or110EditField.Value = "1,1"; 
                    Error = 1;
                end
                
                if Error == 0
            
                    Inputvalue(1,1) = str2double(Input(1:CommaTest(1)-1));
                    Inputvalue(1,2) = str2double(Input(CommaTest+1:end));
                   
                    if isnan(Inputvalue(1)) || isnan(Inputvalue(2))
                        f = msgbox("Format Error for Channel Selection. Example Format: 1,10 or 1,1. Value change to standard");
                        app.RejectChannelFormat11or110EditField.Value = "1,1";
                        Error = 1;
                    end
            
                    if Error == 0
                        if Inputvalue(1) > Inputvalue(2)
                            f = msgbox("Format Error for Channel Selection. Example Format: 1,10 or 1,1. Value change to standard");
                            app.RejectChannelFormat11or110EditField.Value = "1,1";
                            Error = 1;
                        end
                        
                        if Inputvalue(1) <= 0 || Inputvalue(2) <= 0
                            f = msgbox("Format Error for Channel Selection. Example Format: 1,10 or 1,1. Value change to standard");
                            app.RejectChannelFormat11or110EditField.Value = "1,1"; 
                            Error = 1;
                        end
                    end
                end
            end

            %% Get Timewindow and trial information from GUI

            indicesep = find(app.RejectChannelFormat11or110EditField.Value == ',');
            RejectChannel(1,1) = str2double(app.RejectChannelFormat11or110EditField.Value(1:indicesep(1)-1));
            RejectChannel(1,2) = str2double(app.RejectChannelFormat11or110EditField.Value(indicesep+1:end));
            
            if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                [~] = Preprocessing_Events_Channel_Rejection(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.EventTime,RejectChannel,app.Slider.Value,app.UIAxes,"All",app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
            else
                [~] = Preprocessing_Events_Channel_Rejection(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.EventTime,RejectChannel,app.Slider.Value,app.UIAxes,"All",app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
            end
        end

        % Menu selected function: ChangeChannelSpacingLimitsMenu
        function ChangeChannelSpacingLimitsMenuSelected(app, event)
            Channel_Spacing_Selection(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ChannelRejectionWindowUIFigure = uifigure('Visible', 'off');
            app.ChannelRejectionWindowUIFigure.Name = 'Channel Rejection Window';
            app.ChannelRejectionWindowUIFigure.Icon = 'Logo.png';
            app.ChannelRejectionWindowUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.ChannelRejectionWindowUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            zlabel(app.UIAxes, 'Z')
            app.NeuromodLabel = uilabel(app.RightPanel);
            app.NeuromodLabel.Text = 'Neuromod';
            app.SaveasnewDatasetButton = uibutton(app.RightPanel, 'push');
            app.SaveasnewDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @SaveasnewDatasetButtonPushed, true);
            app.SaveasnewDatasetButton.Text = {'Save as new'; 'Dataset'};
            app.Slider = uislider(app.RightPanel);
            app.Slider.Limits = [0.001 0.1];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 0.001;
            app.DataLineSpacingLabel = uilabel(app.RightPanel);
            app.DataLineSpacingLabel.Text = {'Data Line'; 'Spacing'};
            app.ChannelInterpolationWindowLabel = uilabel(app.RightPanel);
            app.ChannelInterpolationWindowLabel.Text = 'Channel Interpolation Window';
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = 'Logo.png';
            app.ChannelSettingsPanel = uipanel(app.RightPanel);
            app.ChannelSettingsPanel.Title = 'Channel Settings';
            app.InterpolateChannelFormat11or110EditFieldLabel = uilabel(app.ChannelSettingsPanel);
            app.InterpolateChannelFormat11or110EditFieldLabel.Text = {'Interpolate Channel:'; '(Format: 1,1 or 1,10)'};
            app.RejectChannelFormat11or110EditField = uieditfield(app.ChannelSettingsPanel, 'text');
            app.RejectChannelFormat11or110EditField.ValueChangedFcn = createCallbackFcn(app, @RejectChannelFormat11or110EditFieldValueChanged, true);
            app.CloseButton = uibutton(app.RightPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.ContextMenu = uicontextmenu(app.ChannelRejectionWindowUIFigure);
            app.ChangeChannelSpacingLimitsMenu = uimenu(app.ContextMenu);
            app.ChangeChannelSpacingLimitsMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeChannelSpacingLimitsMenuSelected, true);
            app.ChangeChannelSpacingLimitsMenu.Text = 'Change Channel Spacing Limits';
            % Assign app.ContextMenu
            app.Slider.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.ChannelRejectionWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Preprocessing_Events_Channel_Rejection_and_Interpolation_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ChannelRejectionWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ChannelRejectionWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ChannelRejectionWindowUIFigure)
        end
    end
end