classdef Preprocessing_Events_Trial_Rejection_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TrialRejectionWindowUIFigure   matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        TrialRejectionSettingsPanel_2  matlab.ui.container.Panel
        TrialsCrossingThresholdLabel   matlab.ui.control.Label
        TextArea                       matlab.ui.control.TextArea
        PlotThresholdCheckBox          matlab.ui.control.CheckBox
        AutomaticDetectionLabel        matlab.ui.control.Label
        ThresholdEditField             matlab.ui.control.EditField
        ThresholdEditFieldLabel        matlab.ui.control.Label
        CloseButton                    matlab.ui.control.Button
        SaveasnewDatasetButton         matlab.ui.control.Button
        TrialRejectionSettingsPanel    matlab.ui.container.Panel
        PlotTrialsfromtoEditField      matlab.ui.control.EditField
        PlotTrialsfromtoLabel          matlab.ui.control.Label
        TrialRejectionSettingsLabel    matlab.ui.control.Label
        ClimfromtoEditField            matlab.ui.control.EditField
        ClimfromtoEditFieldLabel       matlab.ui.control.Label
        RejectTrialsfromtoEditField    matlab.ui.control.EditField
        RejectTrialsfromtoLabel        matlab.ui.control.Label
        ChannelofInterestDropDown      matlab.ui.control.DropDown
        ChannelofInteresjustforplottLabel  matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    %% All of the following code in this section is to display infos when clicking on plots

    properties (Access = public)
        % Add a property to store the names of the vectors
        TextHandle = []; % Store the handle to the displayed text
        TrialsTextHandle = []; % Store the handle to the displayed text
        Mainapp = [];
        EventTime = [];
        EventsRelatedApp = [];
        TempEventRelatedData = [];
    end
        
    
    methods (Access = private)
      
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp, EventsRelatedApp)
            movegui(app.TrialRejectionWindowUIFigure, 'center');

            app.Mainapp = Mainapp;
            app.EventsRelatedApp = EventsRelatedApp;

            app = Utility_Change_Light_Dark_Mode(app,"Event_Trial_Rejection");

            %% Fill GUI fields
            AllChannels = {};
            N = length(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);  
            if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 1
                TempActiveChannel = (str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)*str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)+1)-sort(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
                for i = 1:N
                    AllChannels{i} = num2str(TempActiveChannel(i));
                end
            else
                for i = 1:N
                    AllChannels{i} = num2str(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel(i));
                end
            end

            app.ChannelofInterestDropDown.Items = AllChannels;

            EventRelatedDataTimeRange = [];
                 
            spaceindicie = find(app.Mainapp.Data.Info.EventRelatedDataTimeRange == ' ');
            EventRelatedDataTimeRange(1) = str2double(app.Mainapp.Data.Info.EventRelatedDataTimeRange(1:spaceindicie));
            EventRelatedDataTimeRange(2) = str2double(app.Mainapp.Data.Info.EventRelatedDataTimeRange(spaceindicie+1:end));
            
            app.TempEventRelatedData = app.Mainapp.Data.EventRelatedData;

            if strcmp(app.Mainapp.Data.Info.EventRelatedDataType,'Raw')
                app.EventTime = 0-EventRelatedDataTimeRange(1):1/app.Mainapp.Data.Info.NativeSamplingRate:EventRelatedDataTimeRange(2);
            else
                if isfield(app.Mainapp.Data.Info,"DownsampleFactor")
                    app.EventTime = 0-EventRelatedDataTimeRange(1):1/app.Mainapp.Data.Info.DownsampledSampleRate:EventRelatedDataTimeRange(2);
                else
                    app.EventTime = 0-EventRelatedDataTimeRange(1):1/app.Mainapp.Data.Info.NativeSamplingRate:EventRelatedDataTimeRange(2);
                end
            end
            if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                app.PlotTrialsfromtoEditField.Value = strcat('1:',num2str(size(app.Mainapp.Data.PreprocessedEventRelatedData,2)));
            else
                app.PlotTrialsfromtoEditField.Value = strcat('1:',num2str(size(app.Mainapp.Data.EventRelatedData,2)));
            end
            
            if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                [~,~,~,ThreshTrials] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.EventTime,'Plot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value);
            else
                [~,~,~,ThreshTrials] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.EventTime,'Plot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value);
            end
            
            if ~isempty(ThreshTrials)
             % Convert to comma-separated string
                texttoshow = strjoin(string(ThreshTrials), ',');
            else
                texttoshow = '';
            end
            
            app.TextArea.Value = texttoshow;

            app = Utility_Change_Light_Dark_Mode(app,"Event_Trial_Rejection");
            
        end

        % Button pushed function: SaveasnewDatasetButton
        function SaveasnewDatasetButtonPushed(app, event)

            if isempty(app.RejectTrialsfromtoEditField.Value)
                msgbox("Error: No trigger selected to reject.")
                return;
            else
                if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                    Channel = [1,size(app.Mainapp.Data.PreprocessedEventRelatedData,1)];
                else
                    Channel = [1,size(app.Mainapp.Data.EventRelatedData,1)];
                end
                try
                    RejectTrials = eval(app.RejectTrialsfromtoEditField.Value);
                catch
                    msgbox("Error: Could not determine trigger to reject.")
                    return;
                end
            end
            
            if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                app.Mainapp.Data.PreprocessedEventRelatedData(:,RejectTrials,:) = [];
            else
                numTrials = size(app.Mainapp.Data.EventRelatedData, 2);
                trialsToKeep = true(1, numTrials);
                trialsToKeep(RejectTrials) = false;
                app.Mainapp.Data.PreprocessedEventRelatedData = app.Mainapp.Data.EventRelatedData(:,trialsToKeep,:);
            end

            [app.Mainapp.Data] = Preprocessing_Events_Add_Preprocessing_Info(app.Mainapp.Data,'Trial Rejection',Channel,RejectTrials,[]);

            Utility_Show_Info_Loaded_Data(app.Mainapp);

            Preprocessing_Events_PopulateInfoText(app.EventsRelatedApp.TextArea,app.Mainapp.Data);

            msgbox("Rejection of selected trigger succesfull.");
         
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: ChannelofInterestDropDown
        function ChannelofInterestDropDownValueChanged(app, event)
        
             if ~isempty(app.RejectTrialsfromtoEditField.Value)

                if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                    [~,~,~,ThreshTrials] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.EventTime,'RejectandPlot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value);
                else
                    [~,~,~,ThreshTrials] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.EventTime,'RejectandPlot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value);
                end

            else
                %% Get Data based on Input
                if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                    [~,~,~,ThreshTrials] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.EventTime,'Plot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value);
                else
                    [~,~,~,ThreshTrials] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.EventTime,'Plot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value);
                end
            end
             
            if ~isempty(ThreshTrials)
             % Convert to comma-separated string
                texttoshow = strjoin(string(ThreshTrials), ',');
            else
                texttoshow = '';
            end
            
            app.TextArea.Value = texttoshow;

            app = Utility_Change_Light_Dark_Mode(app,"Event_Trial_Rejection");
        end

        % Value changed function: ClimfromtoEditField
        function ClimfromtoEditFieldValueChanged(app, event)
            value = app.ClimfromtoEditField.Value;
            
            CommaTest = find(value == ',');
            Inputvalue(1,1) = str2double(value(1:CommaTest(1)-1));
            Inputvalue(1,2) = str2double(value(CommaTest+1:end));

            app.UIAxes.CLim = [Inputvalue(1),Inputvalue(2)];
        end

        % Value changed function: RejectTrialsfromtoEditField
        function RejectTrialsfromtoEditFieldValueChanged(app, event)
            
            if contains(app.RejectTrialsfromtoEditField.Value,',')
                if ~contains(app.RejectTrialsfromtoEditField.Value,'[') || ~contains(app.RejectTrialsfromtoEditField.Value,']')
                    app.RejectTrialsfromtoEditField.Value = strcat('[',app.RejectTrialsfromtoEditField.Value,']');
                end
            end
            

            if ~isempty(app.RejectTrialsfromtoEditField.Value)
            %% Get Data based on Input

                if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                    [app.TempEventRelatedData,~,~,ThreshTrials] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.EventTime,'RejectandPlot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value);
                else
                    [app.TempEventRelatedData,~,~,ThreshTrials] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.EventTime,'RejectandPlot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value);
                end

            else
                if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                    [app.TempEventRelatedData,~,~,ThreshTrials] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.EventTime,'Plot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value);
                else
                    [app.TempEventRelatedData,~,~,ThreshTrials] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.EventTime,'Plot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value);
                end

            end

            if isempty(app.RejectTrialsfromtoEditField.Value)
                app.TempEventRelatedData = [];
            end

            if ~isempty(ThreshTrials)
             % Convert to comma-separated string
                texttoshow = strjoin(string(ThreshTrials), ',');
            else
                texttoshow = '';
            end
            
            app.TextArea.Value = texttoshow;

            app = Utility_Change_Light_Dark_Mode(app,"Event_Trial_Rejection");
        end

        % Value changed function: PlotTrialsfromtoEditField
        function PlotTrialsfromtoEditFieldValueChanged(app, event)
             if contains(app.PlotTrialsfromtoEditField.Value,',')
                if ~contains(app.PlotTrialsfromtoEditField.Value,'[') || ~contains(app.PlotTrialsfromtoEditField.Value,']')
                    app.PlotTrialsfromtoEditField.Value = strcat('[',app.PlotTrialsfromtoEditField.Value,']');
                end
             end

             if ~isempty(app.RejectTrialsfromtoEditField.Value)
                %% Get Data based on Input
                if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                    [~,~,~,ThreshTrials] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.EventTime,'RejectandPlot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value);
                else
                    [~,~,~,ThreshTrials] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.EventTime,'RejectandPlot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value);
                end

            else
                %% Get Data based on Input
                if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                    [~,~,~,ThreshTrials] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.EventTime,'Plot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value);
                else
                    [~,~,~,ThreshTrials] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.EventTime,'Plot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value);
                end

            end
             
            if ~isempty(ThreshTrials)
             % Convert to comma-separated string
                texttoshow = strjoin(string(ThreshTrials), ',');
            else
                texttoshow = '';
            end
            
            app.TextArea.Value = texttoshow;

            app = Utility_Change_Light_Dark_Mode(app,"Event_Trial_Rejection");
        end

        % Value changed function: PlotThresholdCheckBox
        function PlotThresholdCheckBoxValueChanged(app, event)
            value = app.PlotThresholdCheckBox.Value;
            if ~isempty(app.RejectTrialsfromtoEditField.Value)
                %% Get Data based on Input
                if ~isempty(app.TempEventRelatedData)
                    EventRelatedData = app.TempEventRelatedData;
                else
                    if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                        EventRelatedData = app.Mainapp.Data.PreprocessedEventRelatedData;
                    else
                        EventRelatedData = app.Mainapp.Data.EventRelatedData;
                    end
                end


                if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                    [~,~,~,ThreshTrials] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,EventRelatedData,app.EventTime,'RejectandPlot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value);
                else
                    [~,~,~,ThreshTrials] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,EventRelatedData,app.EventTime,'RejectandPlot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value);
                end

            else
                %% Get Data based on Input
                if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                    [~,~,~,ThreshTrials] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.EventTime,'Plot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value);
                else
                    [~,~,~,ThreshTrials] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.EventTime,'Plot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value);
                end
            end
            
            if ~isempty(ThreshTrials)
             % Convert to comma-separated string
                texttoshow = strjoin(string(ThreshTrials), ',');
            else
                texttoshow = '';
            end
            
            app.TextArea.Value = texttoshow;
            app = Utility_Change_Light_Dark_Mode(app,"Event_Trial_Rejection");
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            value = app.ThresholdEditField.Value;
            if ~isempty(app.RejectTrialsfromtoEditField.Value)
                %% Get Data based on Input
                if ~isempty(app.TempEventRelatedData)
                    EventRelatedData = app.TempEventRelatedData;
                else
                    if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                        EventRelatedData = app.Mainapp.Data.PreprocessedEventRelatedData;
                    else
                        EventRelatedData = app.Mainapp.Data.EventRelatedData;
                    end
                end

                if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                    [~,~,~,ThreshTrials] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,EventRelatedData,app.EventTime,'RejectandPlot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value);
                else
                    [~,~,~,ThreshTrials] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,EventRelatedData,app.EventTime,'RejectandPlot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value);
                end

            else
                %% Get Data based on Input
                if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                    [~,~,~,ThreshTrials] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.EventTime,'Plot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value);
                else
                    [~,~,~,ThreshTrials] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.EventTime,'Plot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value);
                end
            end
            
            if ~isempty(ThreshTrials)
             % Convert to comma-separated string
                texttoshow = strjoin(string(ThreshTrials), ',');
            else
                texttoshow = '';
            end
            
            app.TextArea.Value = texttoshow;
            app = Utility_Change_Light_Dark_Mode(app,"Event_Trial_Rejection");
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.TrialRejectionWindowUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {704, 704};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {303, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TrialRejectionWindowUIFigure = uifigure('Visible', 'off');
            app.TrialRejectionWindowUIFigure.Name = 'Trigger Rejection Window';
            app.TrialRejectionWindowUIFigure.Icon = 'Logo.png';
            app.TrialRejectionWindowUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.TrialRejectionWindowUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TrialRejectionSettingsPanel = uipanel(app.LeftPanel);
            app.TrialRejectionSettingsPanel.Title = 'Trial Rejection Settings';
            app.ChannelofInteresjustforplottLabel = uilabel(app.TrialRejectionSettingsPanel);
            app.ChannelofInteresjustforplottLabel.Text = 'Channel to Plot';
            app.ChannelofInterestDropDown = uidropdown(app.TrialRejectionSettingsPanel);
            app.ChannelofInterestDropDown.Items = {};
            app.ChannelofInterestDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelofInterestDropDownValueChanged, true);
            app.ChannelofInterestDropDown.Value = {};
            app.RejectTrialsfromtoLabel = uilabel(app.TrialRejectionSettingsPanel);
            app.RejectTrialsfromtoLabel.Text = {'Reject Trials'; '(from,to)'};
            app.RejectTrialsfromtoEditField = uieditfield(app.TrialRejectionSettingsPanel, 'text');
            app.RejectTrialsfromtoEditField.ValueChangedFcn = createCallbackFcn(app, @RejectTrialsfromtoEditFieldValueChanged, true);
            app.ClimfromtoEditFieldLabel = uilabel(app.TrialRejectionSettingsPanel);
            app.ClimfromtoEditFieldLabel.Text = {'Clim'; '[from , to]'};
            app.ClimfromtoEditField = uieditfield(app.TrialRejectionSettingsPanel, 'text');
            app.ClimfromtoEditField.ValueChangedFcn = createCallbackFcn(app, @ClimfromtoEditFieldValueChanged, true);
            app.TrialRejectionSettingsLabel = uilabel(app.TrialRejectionSettingsPanel);
            app.TrialRejectionSettingsLabel.Text = 'Trial Rejection Settings';
            app.PlotTrialsfromtoLabel = uilabel(app.TrialRejectionSettingsPanel);
            app.PlotTrialsfromtoLabel.Text = {'Plot Trials'; '(from,to)'};
            app.PlotTrialsfromtoEditField = uieditfield(app.TrialRejectionSettingsPanel, 'text');
            app.PlotTrialsfromtoEditField.ValueChangedFcn = createCallbackFcn(app, @PlotTrialsfromtoEditFieldValueChanged, true);
            app.SaveasnewDatasetButton = uibutton(app.LeftPanel, 'push');
            app.SaveasnewDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @SaveasnewDatasetButtonPushed, true);
            app.SaveasnewDatasetButton.Text = {'Save as '; 'new Dataset'};
            app.CloseButton = uibutton(app.LeftPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.TrialRejectionSettingsPanel_2 = uipanel(app.LeftPanel);
            app.TrialRejectionSettingsPanel_2.Title = 'Trial Rejection Settings';
            app.ThresholdEditFieldLabel = uilabel(app.TrialRejectionSettingsPanel_2);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.TrialRejectionSettingsPanel_2, 'text');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdEditField.Value = '1';
            app.AutomaticDetectionLabel = uilabel(app.TrialRejectionSettingsPanel_2);
            app.AutomaticDetectionLabel.Text = 'Automatic Detection';
            app.PlotThresholdCheckBox = uicheckbox(app.TrialRejectionSettingsPanel_2);
            app.PlotThresholdCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotThresholdCheckBoxValueChanged, true);
            app.PlotThresholdCheckBox.Text = '   Plot Threshold';
            app.PlotThresholdCheckBox.Value = true;
            app.TextArea = uitextarea(app.TrialRejectionSettingsPanel_2);
            app.TrialsCrossingThresholdLabel = uilabel(app.TrialRejectionSettingsPanel_2);
            app.TrialsCrossingThresholdLabel.Text = 'Trials Crossing Threshold';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes_2 = uiaxes(app.RightPanel);
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes = uiaxes(app.RightPanel);
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.TrialRejectionWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Preprocessing_Events_Trial_Rejection_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.TrialRejectionWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.TrialRejectionWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TrialRejectionWindowUIFigure)
        end
    end
end