classdef SpikeInterface_SpikeSorting_Parameter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpikeInterfaceParameterSelectionUIFigure  matlab.ui.Figure
        CloseButton                     matlab.ui.control.Button
        SaveSettingsButton              matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        NeuromodLabel                   matlab.ui.control.Label
        SpikeInterfaceParameterSelectionLabel  matlab.ui.control.Label
        GeneralSettingsPanel            matlab.ui.container.Panel
        LoadSpikeSortingCheckBox        matlab.ui.control.CheckBox
        LoopovermultipleRecordingsCheckBox  matlab.ui.control.CheckBox
        PlotTracesCheckBox              matlab.ui.control.CheckBox
        SorterDropDown                  matlab.ui.control.DropDown
        SorterDropDownLabel             matlab.ui.control.Label
        PlotsomesortingresultsCheckBox  matlab.ui.control.CheckBox
        PreprocessDataBandPassandWhiteningCheckBox  matlab.ui.control.CheckBox
        OpenSpikeInterfaceGUIaftersortingCheckBox  matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        Mainapp = []; % Description
        SpikeDetectionapp = [];
        SelectedSettings = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp, SpikeDetectionapp)
            movegui(app.SpikeInterfaceParameterSelectionUIFigure, 'center');

            app.Mainapp = Mainapp;
            app.SpikeDetectionapp = SpikeDetectionapp;


        end

        % Button pushed function: SaveSettingsButton
        function SaveSettingsButtonPushed(app, event)
            app.SelectedSettings.OpenSpikeInterface = double(app.OpenSpikeInterfaceGUIaftersortingCheckBox.Value);
            app.SelectedSettings.Preprocess = double(app.PreprocessDataBandPassandWhiteningCheckBox.Value);
            app.SelectedSettings.PlotTraces = double(app.PlotTracesCheckBox.Value);
            app.SelectedSettings.PlotSortingResults = double(app.PlotsomesortingresultsCheckBox.Value);           
            app.SelectedSettings.Sorter = app.SorterDropDown.Value;
            app.SelectedSettings.MultipleRecordings = double(app.LoopovermultipleRecordingsCheckBox.Value);
            app.SelectedSettings.LoadSorting = double(app.LoadSpikeSortingCheckBox.Value);

            app.SpikeDetectionapp.SpikeInterfaceParameter = app.SelectedSettings;

            app.SpikeDetectionapp.SortingParameterWindow = [];

            delete(app);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.SpikeDetectionapp.SortingParameterWindow = [];

            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpikeInterfaceParameterSelectionUIFigure = uifigure('Visible', 'off');
            app.SpikeInterfaceParameterSelectionUIFigure.Name = 'SpikeInterface Parameter Selection';
            app.SpikeInterfaceParameterSelectionUIFigure.Icon = 'Logo.png';
            app.GeneralSettingsPanel = uipanel(app.SpikeInterfaceParameterSelectionUIFigure);
            app.GeneralSettingsPanel.Title = 'General Settings';
            app.OpenSpikeInterfaceGUIaftersortingCheckBox = uicheckbox(app.GeneralSettingsPanel);
            app.OpenSpikeInterfaceGUIaftersortingCheckBox.Text = {'   Open SpikeInterface GUI '; '   after sorting'};
            app.OpenSpikeInterfaceGUIaftersortingCheckBox.Value = true;
            app.PreprocessDataBandPassandWhiteningCheckBox = uicheckbox(app.GeneralSettingsPanel);
            app.PreprocessDataBandPassandWhiteningCheckBox.Text = {'   Preprocess Data '; '   (Band-Pass and Whitening)'};
            app.PreprocessDataBandPassandWhiteningCheckBox.Value = true;
            app.PlotsomesortingresultsCheckBox = uicheckbox(app.GeneralSettingsPanel);
            app.PlotsomesortingresultsCheckBox.Text = '   Plot some sorting results ';
            app.SorterDropDownLabel = uilabel(app.GeneralSettingsPanel);
            app.SorterDropDownLabel.Text = 'Sorter';
            app.SorterDropDown = uidropdown(app.GeneralSettingsPanel);
            app.SorterDropDown.Items = {'Mountainsort 5', 'SpykingCircus 2'};
            app.SorterDropDown.Value = 'Mountainsort 5';
            app.PlotTracesCheckBox = uicheckbox(app.GeneralSettingsPanel);
            app.PlotTracesCheckBox.Text = '   Plot Traces';
            app.LoopovermultipleRecordingsCheckBox = uicheckbox(app.GeneralSettingsPanel);
            app.LoopovermultipleRecordingsCheckBox.Text = '   Loop over multiple Recordings';
            app.LoadSpikeSortingCheckBox = uicheckbox(app.GeneralSettingsPanel);
            app.LoadSpikeSortingCheckBox.Text = '   Load Spike Sorting';
            app.SpikeInterfaceParameterSelectionLabel = uilabel(app.SpikeInterfaceParameterSelectionUIFigure);
            app.SpikeInterfaceParameterSelectionLabel.Text = {'SpikeInterface Parameter'; 'Selection'};
            app.NeuromodLabel = uilabel(app.SpikeInterfaceParameterSelectionUIFigure);
            app.NeuromodLabel.Text = 'Neuromod';
            app.Image = uiimage(app.SpikeInterfaceParameterSelectionUIFigure);
            app.Image.ImageSource = 'Logo.png';
            app.SaveSettingsButton = uibutton(app.SpikeInterfaceParameterSelectionUIFigure, 'push');
            app.SaveSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSettingsButtonPushed, true);
            app.SaveSettingsButton.Text = 'Save Settings';
            app.CloseButton = uibutton(app.SpikeInterfaceParameterSelectionUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.SpikeInterfaceParameterSelectionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpikeInterface_SpikeSorting_Parameter(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SpikeInterfaceParameterSelectionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpikeInterfaceParameterSelectionUIFigure)
        end
    end
end