classdef Spike_Detection_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpikeDetectionWindowUIFigure    matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        RightPanel                      matlab.ui.container.Panel
        Wave_clus3SpikeSortingSettingsPanel  matlab.ui.container.Panel
        RUNButton                       matlab.ui.control.Button
        SortallChannelTogetherCheckBox  matlab.ui.control.CheckBox
        SortforIndividualChannelCheckBox  matlab.ui.control.CheckBox
        OptionsDropDown                 matlab.ui.control.DropDown
        OptionsDropDownLabel            matlab.ui.control.Label
        SpikeDetectionSettingsPanel     matlab.ui.container.Panel
        MeanDropDown                    matlab.ui.control.DropDown
        MeanandStdDropDownLabel         matlab.ui.control.Label
        ThresholdEditField              matlab.ui.control.EditField
        ThresholdEditFieldLabel         matlab.ui.control.Label
        DetectionMethodDropDown         matlab.ui.control.DropDown
        DetectionMethodDropDownLabel    matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        InformationLabel                matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        CloseButton                     matlab.ui.control.Button
        NeuromodLabel                   matlab.ui.control.Label
        SpikeDetectionWindowLabel       matlab.ui.control.Label
        SpikeFilteringSettingsPanel     matlab.ui.container.Panel
        StartSpikeDetectionButton       matlab.ui.control.Button
        TimeOffsettoCombineSpikeIndiciessEditField  matlab.ui.control.EditField
        TimeSpantoCombineSpikeIndiciesEditFieldLabel  matlab.ui.control.Label
        FilterDoubleSpikeIndiciesCheckBox  matlab.ui.control.CheckBox
        VerticalSpikeOffsetToleranceSamplesEditField  matlab.ui.control.EditField
        VerticalSpikeOffsetToleranceSamplesEditFieldLabel  matlab.ui.control.Label
        MinDepthofArtefactmEditField    matlab.ui.control.EditField
        MinDepthofArtefactmEditFieldLabel  matlab.ui.control.Label
        FilterVerticalSpikeArtefactsCheckBox  matlab.ui.control.CheckBox
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Mainapp = []; % Description
        SpikeSortingPath = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            movegui(app.SpikeDetectionWindowUIFigure, 'center');

            app.Mainapp = Mainapp;

            %% Check if high pass filtered data is available. If not, display error message and return
            if ~isfield(app.Mainapp.Data,'Preprocessed')
                msgbox("Error: No high pass filtered data detected. Spike detection on raw data wont work. High pass filter data and open this window again!");
                disp("Error: No high pass filtered data detected. Spike detection on raw data wont work. High pass filter data and open this window again!");
                app.Mainapp.SpikeExtractionWindow = [];
                delete(app);
                return;
            else
                if ~isfield(app.Mainapp.Data.Info,'FilterMethod')
                    msgbox("Error: No high pass filtered data detected. Spike detection on raw data wont work. High pass filter data and open this window again!");
                    disp("Error: No high pass filtered data detected. Spike detection on raw data wont work. High pass filter data and open this window again!");
                    app.Mainapp.SpikeExtractionWindow = [];
                    delete(app);
                    return;
                else
                    if ~strcmp(app.Mainapp.Data.Info.FilterMethod,"High-Pass")
                        msgbox("Error: No high pass filtered data detected. Spike detection on raw data wont work. High pass filter data and open this window again!");
                        disp("Error: No high pass filtered data detected. Spike detection on raw data wont work. High pass filter data and open this window again!");
                        app.Mainapp.SpikeExtractionWindow = [];
                        delete(app);
                        return;
                    end
                end
            end

            if isfield(app.Mainapp.Data,'Spikes') && strcmp(app.Mainapp.Data.Info.SpikeType,"Internal")
                SpikeText = strcat("Existing Spike Data was found with threshold ",num2str(app.Mainapp.Data.Info.SpikeDetectionNrStd));
            else
                SpikeText = strcat("No Existing Internal Spike Data was found");
            end

            app.VerticalSpikeOffsetToleranceSamplesEditField.Enable = "off";
            app.MinDepthofArtefactmEditField.Enable = "off";

            % Check if spike sorting was already saved
            % Check if the folder exists
            app.SpikeSortingPath = strcat(app.Mainapp.Data.Info.Data_Path,'\Wave_Clus');
            
            cluster_class = [];

            if isfield(app.Mainapp.Data,'Spikes') && strcmp(app.Mainapp.Data.Info.SpikeType,'Internal')
                SpikeText = strcat("Spike data already part of dataset with threshold ",num2str(app.Mainapp.Data.Info.SpikeDetectionNrStd),". If you want to load spike sorting data make sure the threshold is the same. Otherwise current spike dta is overwritten when loading sorting results.");
            else
                SpikeText = strcat("No Existing Internal Spike Data was found");
            end
            
            if ~isfolder(app.SpikeSortingPath) % No sorting found
                textboxx = strcat("Path: ",app.SpikeSortingPath," does not contain spike sorting results folder or path is not available anymore. Eihter start a new sorting or load a different folder");
                SpikeExtractiontext = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";
                SpikeExtractiontext2 = "'Filter Double Spike Indicies' is looking for spikes indicies part of the same waveform. 'Time Offset to Combine SpikeIndicies' is the length of that waveform and is added to each spike time. When the next spike is within the current spike time plus the time offset, they get combined to one spike.";
                textboxx = [SpikeText;"";SpikeExtractiontext;"";SpikeExtractiontext2;"";textboxx];
                app.TextArea.Value = textboxx;
            else

                textboxx = strcat("Spike Sorting Data found in: ",app.SpikeSortingPath,". Select the 'Load Sorting Results' option and press 'RUN' to load spike sorting.");

                if isfile(strcat(strcat(app.SpikeSortingPath,'\times_spikes.mat')))
                    load(strcat(strcat(app.SpikeSortingPath,'\times_spikes.mat')));
                    SortingType = "AllChannel";
                    textboxx = [textboxx;"";"Type: Sorted all channel together."];
                else
                    if isfile(strcat(strcat(app.SpikeSortingPath,'\times_CH',num2str(1),'.mat')))
                        load(strcat(strcat(app.SpikeSortingPath,'\times_CH',num2str(1),'.mat')));
                        SortingType = "IndividualChannel";
                        textboxx = [textboxx;"";"Type: Sorted channel individually."];
                    else
                        textboxx = strcat("Path: ",app.SpikeSortingPath," does not contain spike sorting results folder or path is not available anymore. Eihter start a new sorting or load a different folder");
                        SpikeExtractiontext = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";
                        SpikeExtractiontext2 = "'Filter Double Spike Indicies' is looking for spikes indicies part of the same waveform. 'Time Offset to Combine SpikeIndicies' is the length of that waveform and is added to each spike time. When the next spike is within the current spike time plus the time offset, they get combined to one spike.";
                        textboxx = [SpikeText;"";SpikeExtractiontext;"";SpikeExtractiontext2;"";textboxx];
                        app.TextArea.Value = textboxx;
                        return;
                    end
                end

                if strcmp(SortingType,"AllChannel")
                    numclu = length(unique(cluster_class(:,1)));
    
                    textboxx = [textboxx;" ";strcat("Number of Cluster: ",num2str(numclu));""];
                    
                    % Initialize a string array to store the output
                    outputText = "";
                    
                    % Get all field names from the structure
                    fields = fieldnames(par);
    
                    % Loop through each field and concatenate the field name and value
                    for i = 1:numel(fields)
                        fieldName = fields{i};
                        fieldValue = par.(fieldName);
                        
                        % Convert the field value to a string if it is not already
                        if isnumeric(fieldValue)
                            fieldValue = num2str(fieldValue);
                        elseif ischar(fieldValue)
                            fieldValue = string(fieldValue);
                        end
                        
                        % Concatenate the field name and value
                        outputText = outputText + fieldName + ": " + fieldValue + newline;
                    end
                    
                    % Set the TextArea value in the app (assuming app.TextArea is the name of the TextArea)
                    textboxx = [textboxx;outputText];
                    SpikeExtractiontext = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";
                    SpikeExtractiontext2 = "'Filter Double Spike Indicies' is looking for spikes indicies part of the same waveform. 'Time Offset to Combine SpikeIndicies' is the length of that waveform and is added to each spike time. When the next spike is within the current spike time plus the time offset, they get combined to one spike.";
                    textboxx = [SpikeText;"";SpikeExtractiontext;"";SpikeExtractiontext2;"";textboxx;outputText];
    
                    app.TextArea.Value = textboxx;
                else
                    if isfield(app.Mainapp.Data,'Spikes') && strcmp(app.Mainapp.Data.Info.SpikeType,'Internal')
                        SpikeText = strcat("Spike data already part of dataset with threshold ",num2str(app.Mainapp.Data.Info.SpikeDetectionNrStd),". If you want to load spike sorting data make sure the threshold is the same. Otherwise current spike dta is overwritten when loading sorting results.");
                    else
                        SpikeText = strcat("No Existing Internal Spike Data was found");
                    end

                    SpikeExtractiontext = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";
                    SpikeExtractiontext2 = "'Filter Double Spike Indicies' is looking for spikes indicies part of the same waveform. 'Time Offset to Combine SpikeIndicies' is the length of that waveform and is added to each spike time. When the next spike is within the current spike time plus the time offset, they get combined to one spike.";
                    textboxx = [SpikeText;"";textboxx;"";SpikeExtractiontext;"";SpikeExtractiontext2];

                    app.TextArea.Value = textboxx;
                end
            end
           
        end

        % Button pushed function: StartSpikeDetectionButton
        function StartSpikeDetectionButtonPushed(app, event)
            disp(strcat("Extracting Spikes. Please wait until the progress bar closes"));

            %% Spike Detection
            [app.Mainapp.Data,~] = Spike_Module_Spike_Detection(app.Mainapp.Data,app.DetectionMethodDropDown.Value,app.MeanDropDown.Value,str2double(app.ThresholdEditField.Value),app.FilterVerticalSpikeArtefactsCheckBox.Value,app.VerticalSpikeOffsetToleranceSamplesEditField.Value,app.MinDepthofArtefactmEditField.Value,app.FilterDoubleSpikeIndiciesCheckBox.Value,str2double(app.TimeOffsettoCombineSpikeIndiciessEditField.Value));

            %% Aftmeramth: Resetting Main window Plots and plot data again
            disp(strcat("Spike detection finished. They can now be visualized and analyzed in the main window"));

            app.Mainapp.UIAxes.NextPlot = "replace"; 
            plot(app.Mainapp.UIAxes,0,0);
            app.Mainapp.UIAxes.NextPlot = "add"; 
            app.Mainapp.UIAxes_2.NextPlot = "replace"; 
            plot(app.Mainapp.UIAxes_2,0,0);
            app.Mainapp.UIAxes_2.NextPlot = "add"; 
            app.Mainapp.UIAxes.Box = "off";

            %Take care of potentially changed backgroundcolor
            app.Mainapp.UIAxes.Color = app.Mainapp.PlotAppearance.MainWindow.Data.Color.MainBackground;
            app.Mainapp.UIAxes_2.Color = app.Mainapp.PlotAppearance.MainWindow.Data.Color.TimeBackground;
            app.Mainapp.UIAxes.FontSize = app.Mainapp.PlotAppearance.MainWindow.Data.MainFontSize;
            app.Mainapp.UIAxes_2.FontSize = app.Mainapp.PlotAppearance.MainWindow.Data.TimeFontSize;

            if ~isfield(app.Mainapp.Data,'Events')
                if strcmp(app.Mainapp.PlotEvents,"Events")
                    app.Mainapp.PlotEvents = "No";
                end
                Placeholder = {};
                app.Mainapp.DropDown_2.Items = Placeholder;
                app.Mainapp.DropDown_2.Items{1} = 'Non';
            else
                Placeholder = {};
                app.Mainapp.DropDown_2.Items = Placeholder;
                app.Mainapp.DropDown_2.Items{1} = 'Non';
                app.Mainapp.DropDown_2.Items{2} = 'Events';
                if strcmp(app.Mainapp.PlotEvents,"Events")
                    app.Mainapp.DropDown_2.Value = 'Events';
                end
            end
        
            if ~isfield(app.Mainapp.Data,'Spikes')
                % if strcmp(app.Mainapp.Plotspikes,"Spikes")
                %     app.Mainapp.Plotspikes = "No";
                % end
            else
                app.Mainapp.DropDown_2.Items = Placeholder;
                app.Mainapp.DropDown_2.Items{1} = 'Non';
                if isfield(app.Mainapp.Data,'Events')
                    app.Mainapp.DropDown_2.Items{2} = 'Events';
                    app.Mainapp.DropDown_2.Items{3} = 'Spikes';
                else
                    app.Mainapp.DropDown_2.Items{2} = 'Spikes';
                end
                
                if strcmp(app.Mainapp.PlotEvents,"Events")
                    app.Mainapp.DropDown_2.Value = 'Events';
                else
                    app.Mainapp.DropDown_2.Value = 'Non';
                end
            end

            if strcmp(app.Mainapp.Plotspikes,"Spikes")
                SpikeHandles = findobj(app.Mainapp.UIAxes, 'Type', 'line', 'Tag', 'Spikes');
                if ~isempty(SpikeHandles)
                    delete(SpikeHandles(:));
                end
                app.Mainapp.Plotspikes = "No";
            end

            Utility_Show_Info_Loaded_Data(app.Mainapp);

            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","SpikeExtraction",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);

            %% Fill GUI textbox with new data
            % Check if spike sorting was already saved
            % Check if the folder exists
            if isfield(app.Mainapp.Data,'Spikes') && strcmp(app.Mainapp.Data.Info.SpikeType,"Internal")
                SpikeText = strcat("Existing Spike Data was found with threshold ",num2str(app.Mainapp.Data.Info.SpikeDetectionNrStd));
            else
                SpikeText = strcat("No Existing Internal Spike Data was found");
            end

            app.SpikeSortingPath = strcat(app.Mainapp.Data.Info.Data_Path,'\Wave_Clus');
            
            if isfield(app.Mainapp.Data,'Spikes') && strcmp(app.Mainapp.Data.Info.SpikeType,'Internal')
                SpikeText = strcat("Spike data already part of dataset with threshold ",num2str(app.Mainapp.Data.Info.SpikeDetectionNrStd),". If you want to load spike sorting data make sure the threshold is the same. Otherwise current spike dta is overwritten when loading sorting results.");
            else
                SpikeText = strcat("No Existing Internal Spike Data was found");
            end
            
            if ~isfolder(app.SpikeSortingPath) % No sorting found
                textboxx = strcat("Path: ",app.SpikeSortingPath," does not contain spike sorting results folder or path is not available anymore. Eihter start a new sorting or load a different folder");
                SpikeExtractiontext = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";
                SpikeExtractiontext2 = "'Filter Double Spike Indicies' is looking for spikes indicies part of the same waveform. 'Time Offset to Combine SpikeIndicies' is the length of that waveform and is added to each spike time. When the next spike is within the current spike time plus the time offset, they get combined to one spike.";
                    textboxx = [SpikeText;"";SpikeExtractiontext;"";SpikeExtractiontext2;"";textboxx];
                app.TextArea.Value = textboxx;
            else

                textboxx = strcat("Spike Sorting Data found in: ",app.SpikeSortingPath,". Select the 'Load Sorting Results' option and press 'RUN' to load spike sorting.");

                if isfile(strcat(strcat(app.SpikeSortingPath,'\times_spikes.mat')))
                    load(strcat(strcat(app.SpikeSortingPath,'\times_spikes.mat')));
                    SortingType = "AllChannel";
                    textboxx = [textboxx;"";"Type: Sorted all channel together."];
                else
                    if isfile(strcat(strcat(app.SpikeSortingPath,'\times_CH',num2str(1),'.mat')))
                        load(strcat(strcat(app.SpikeSortingPath,'\times_CH',num2str(1),'.mat')));
                        SortingType = "IndividualChannel";
                        textboxx = [textboxx;"";"Type: Sorted channel individually."];
                    else
                        textboxx = strcat("Path: ",app.SpikeSortingPath," does not contain spike sorting results folder or path is not available anymore. Eihter start a new sorting or load a different folder");
                        SpikeExtractiontext = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";
                        SpikeExtractiontext2 = "'Filter Double Spike Indicies' is looking for spikes indicies part of the same waveform. 'Time Offset to Combine SpikeIndicies' is the length of that waveform and is added to each spike time. When the next spike is within the current spike time plus the time offset, they get combined to one spike.";
                        textboxx = [SpikeText;"";SpikeExtractiontext;"";SpikeExtractiontext2;"";textboxx];
                        app.TextArea.Value = textboxx;
                        return;
                    end
                end

                if strcmp(SortingType,"AllChannel")
                    numclu = length(unique(cluster_class(:,1)));
    
                    textboxx = [textboxx;" ";strcat("Number of Cluster: ",num2str(numclu));""];
                    
                    % Initialize a string array to store the output
                    outputText = "";
                    
                    % Get all field names from the structure
                    fields = fieldnames(par);
    
                    % Loop through each field and concatenate the field name and value
                    for i = 1:numel(fields)
                        fieldName = fields{i};
                        fieldValue = par.(fieldName);
                        
                        % Convert the field value to a string if it is not already
                        if isnumeric(fieldValue)
                            fieldValue = num2str(fieldValue);
                        elseif ischar(fieldValue)
                            fieldValue = string(fieldValue);
                        end
                        
                        % Concatenate the field name and value
                        outputText = outputText + fieldName + ": " + fieldValue + newline;
                    end
                    
                    % Set the TextArea value in the app (assuming app.TextArea is the name of the TextArea)
                    textboxx = [textboxx;outputText];
                    SpikeExtractiontext = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";
                    SpikeExtractiontext2 = "'Filter Double Spike Indicies' is looking for spikes indicies part of the same waveform. 'Time Offset to Combine SpikeIndicies' is the length of that waveform and is added to each spike time. When the next spike is within the current spike time plus the time offset, they get combined to one spike.";
                    textboxx = [SpikeText;"";SpikeExtractiontext;"";SpikeExtractiontext2;"";textboxx;outputText];
    
                    app.TextArea.Value = textboxx;
                else
                    if isfield(app.Mainapp.Data,'Spikes') && strcmp(app.Mainapp.Data.Info.SpikeType,'Internal')
                        SpikeText = strcat("Spike data already part of dataset with threshold ",num2str(app.Mainapp.Data.Info.SpikeDetectionNrStd),". If you want to load spike sorting data make sure the threshold is the same. Otherwise current spike dta is overwritten when loading sorting results.");
                    else
                        SpikeText = strcat("No Existing Internal Spike Data was found");
                    end

                    SpikeExtractiontext = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";
                    SpikeExtractiontext2 = "'Filter Double Spike Indicies' is looking for spikes indicies part of the same waveform. 'Time Offset to Combine SpikeIndicies' is the length of that waveform and is added to each spike time. When the next spike is within the current spike time plus the time offset, they get combined to one spike.";
                    textboxx = [SpikeText;"";textboxx;"";SpikeExtractiontext;"";SpikeExtractiontext2];

                    app.TextArea.Value = textboxx;
                end
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.Mainapp.SpikeExtractionWindow = [];
            delete(app);
        end

        % Value changed function: DetectionMethodDropDown
        function DetectionMethodDropDownValueChanged(app, event)
            
        end

        % Value changed function: FilterVerticalSpikeArtefactsCheckBox
        function FilterVerticalSpikeArtefactsCheckBoxValueChanged(app, event)
            value = app.FilterVerticalSpikeArtefactsCheckBox.Value;
            if value == 1
                app.VerticalSpikeOffsetToleranceSamplesEditField.Enable = "on";
                app.MinDepthofArtefactmEditField.Enable = "on";
            else
                app.VerticalSpikeOffsetToleranceSamplesEditField.Enable = "off";
                app.MinDepthofArtefactmEditField.Enable = "off";
            end
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            [app.ThresholdEditField.Value] = Utility_SimpleCheckInputs(app.ThresholdEditField.Value,"One",'4',0,0);
            
        end

        % Value changed function: 
        % VerticalSpikeOffsetToleranceSamplesEditField
        function VerticalSpikeOffsetToleranceSamplesEditFieldValueChanged(app, event)
            [app.VerticalSpikeOffsetToleranceSamplesEditField.Value] = Utility_SimpleCheckInputs(app.VerticalSpikeOffsetToleranceSamplesEditField.Value,"One",'3',0,0);
            
        end

        % Value changed function: MinDepthofArtefactmEditField
        function MinDepthofArtefactmEditFieldValueChanged(app, event)
            [app.MinDepthofArtefactmEditField.Value] = Utility_SimpleCheckInputs(app.MinDepthofArtefactmEditField.Value,"One",'200',0,0);
            
        end

        % Value changed function: 
        % TimeOffsettoCombineSpikeIndiciessEditField
        function TimeOffsettoCombineSpikeIndiciessEditFieldValueChanged(app, event)
            
            [app.TimeOffsettoCombineSpikeIndiciessEditField.Value] = Utility_SimpleCheckInputs(app.TimeOffsettoCombineSpikeIndiciessEditField.Value,"One",'0.002',0,0);
            
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            %% New Spike Sorting
            if strcmp(app.OptionsDropDown.Value,"Start New Spike Sorting")
                if isfield(app.Mainapp.Data,'Spikes') && strcmp(app.Mainapp.Data.Info.SpikeType,'Internal')
                    if isfield(app.Mainapp.Data,'Preprocessed')
                        if isfield(app.Mainapp.Data.Info,'FilterMethod')
                            if strcmp(app.Mainapp.Data.Info.FilterMethod,"High-Pass")
                                if app.SortallChannelTogetherCheckBox.Value == 1 
                                    SortingType = "AllChannelTogether";
                                elseif app.SortforIndividualChannelCheckBox.Value == 1
                                    SortingType = "IndividualChannel";
                                end

                                [app.Mainapp.Data] = Spike_Module_Internal_Spike_Sorting(app.Mainapp.Data,app.SpikeSortingPath,"Clustering",SortingType);
                            else
                                msgbox("No High Pass Filtered Data found! Please first apply a high pass filter to obtain proper waveforms!")
                                return;
                            end
                        else
                            msgbox("No High Pass Filtered Data found! Please first apply a high pass filter to obtain proper waveforms!")
                            return;
                        end
                    else
                        msgbox("No High Pass Filtered Data found! Please first apply a high pass filter to obtain proper waveforms!")
                        return;
                    end
                else
                    msgbox("No Internal Spike Data found. Please first Start Spike Detection!")
                    return;
                end
             %% Change Sorting Parameters
            elseif strcmp(app.OptionsDropDown.Value,"Change Sorting Parameters")
                set_parameters_ui();
            %% Load Sorting
            elseif strcmp(app.OptionsDropDown.Value,"Load Sorting Results")
                if isfield(app.Mainapp.Data,'Spikes') && strcmp(app.Mainapp.Data.Info.SpikeType,'Internal')
                    if isfield(app.Mainapp.Data,'Preprocessed')
                        if isfield(app.Mainapp.Data.Info,'FilterMethod')
                            if strcmp(app.Mainapp.Data.Info.FilterMethod,"High-Pass")
                                [app.Mainapp.Data] = Spike_Module_Internal_Spike_Sorting(app.Mainapp.Data,app.SpikeSortingPath,"Loading");
                            else
                                msgbox("No High Pass Filtered Data found! Please first apply a high pass filter to obtain proper waveforms!")
                                return;
                            end
                        else
                            msgbox("No High Pass Filtered Data found! Please first apply a high pass filter to obtain proper waveforms!")
                            return;
                        end
                    else
                        msgbox("No High Pass Filtered Data found! Please first apply a high pass filter to obtain proper waveforms!")
                        return;
                    end
                else
                    [app.Mainapp.Data] = Spike_Module_Internal_Spike_Sorting(app.Mainapp.Data,app.SpikeSortingPath,"Loading");
                end
            %% Manually select folder with sorting results
            elseif strcmp(app.OptionsDropDown.Value,"Manually Select Folder with Sorting Results")

                if isfield(app.Mainapp.Data,'Spikes') && strcmp(app.Mainapp.Data.Info.SpikeType,'Internal')
                    SpikeText = strcat("Spike data already part of dataset with threshold ",num2str(app.Mainapp.Data.Info.SpikeDetectionNrStd),". If you want to load spike sorting data make sure the threshold is the same. Otherwise current spike dta is overwritten when loading sorting results.");
                else
                    SpikeText = strcat("No Existing Internal Spike Data was found");
                end
    
                folderPath = uigetdir();
                
                if isempty(folderPath)
                    return;
                end
    
                % Check if spike sorting was already saved
                % Check if the folder exists
                app.SpikeSortingPath = strcat(folderPath);
                
                if ~isfolder(app.SpikeSortingPath) % No sorting found
                    textboxx = strcat("Path: ",app.SpikeSortingPath," does not contain spike sorting results folder or path is not available anymore. Eihter start a new sorting or load a different folder");
                    SpikeExtractiontext = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";
                    SpikeExtractiontext2 = "'Filter Double Spike Indicies' is looking for spikes indicies part of the same waveform. 'Time Offset to Combine SpikeIndicies' is the length of that waveform and is added to each spike time. When the next spike is within the current spike time plus the time offset, they get combined to one spike.";
                        textboxx = [SpikeText;"";SpikeExtractiontext;"";SpikeExtractiontext2;"";textboxx];
                    app.TextArea.Value = textboxx;
                else
    
                    textboxx = strcat("Spike Sorting Data found in: ",app.SpikeSortingPath,". Select the 'Load Sorting Results' option and press 'RUN' to load spike sorting.");
    
                    if isfile(strcat(strcat(app.SpikeSortingPath,'\times_spikes.mat')))
                        load(strcat(strcat(app.SpikeSortingPath,'\times_spikes.mat')));
                        SortingType = "AllChannel";
                        textboxx = [textboxx;"";"Type: Sorted all channel together."];
                    else
                        if isfile(strcat(strcat(app.SpikeSortingPath,'\times_CH',num2str(1),'.mat')))
                            load(strcat(strcat(app.SpikeSortingPath,'\times_CH',num2str(1),'.mat')));
                            SortingType = "IndividualChannel";
                            textboxx = [textboxx;"";"Type: Sorted channel individually."];
                        else
                            textboxx = strcat("Path: ",app.SpikeSortingPath," does not contain spike sorting results folder or path is not available anymore. Eihter start a new sorting or load a different folder");
                            SpikeExtractiontext = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";
                            SpikeExtractiontext2 = "'Filter Double Spike Indicies' is looking for spikes indicies part of the same waveform. 'Time Offset to Combine SpikeIndicies' is the length of that waveform and is added to each spike time. When the next spike is within the current spike time plus the time offset, they get combined to one spike.";
                            textboxx = [SpikeText;"";SpikeExtractiontext;"";SpikeExtractiontext2;"";textboxx];
                            app.TextArea.Value = textboxx;
                            return;
                        end
                    end
    
                    if strcmp(SortingType,"AllChannel")
                        numclu = length(unique(cluster_class(:,1)));
        
                        textboxx = [textboxx;" ";strcat("Number of Cluster: ",num2str(numclu));""];
                        
                        % Initialize a string array to store the output
                        outputText = "";
                        
                        % Get all field names from the structure
                        fields = fieldnames(par);
        
                        % Loop through each field and concatenate the field name and value
                        for i = 1:numel(fields)
                            fieldName = fields{i};
                            fieldValue = par.(fieldName);
                            
                            % Convert the field value to a string if it is not already
                            if isnumeric(fieldValue)
                                fieldValue = num2str(fieldValue);
                            elseif ischar(fieldValue)
                                fieldValue = string(fieldValue);
                            end
                            
                            % Concatenate the field name and value
                            outputText = outputText + fieldName + ": " + fieldValue + newline;
                        end
                        
                        % Set the TextArea value in the app (assuming app.TextArea is the name of the TextArea)
                        textboxx = [textboxx;outputText];
                        SpikeExtractiontext = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";
                        SpikeExtractiontext2 = "'Filter Double Spike Indicies' is looking for spikes indicies part of the same waveform. 'Time Offset to Combine SpikeIndicies' is the length of that waveform and is added to each spike time. When the next spike is within the current spike time plus the time offset, they get combined to one spike.";
                        textboxx = [SpikeText;"";SpikeExtractiontext;"";SpikeExtractiontext2;"";textboxx;outputText];
        
                        app.TextArea.Value = textboxx;
                    else
                        if isfield(app.Mainapp.Data,'Spikes') && strcmp(app.Mainapp.Data.Info.SpikeType,'Internal')
                            SpikeText = strcat("Spike data already part of dataset with threshold ",num2str(app.Mainapp.Data.Info.SpikeDetectionNrStd),". If you want to load spike sorting data make sure the threshold is the same. Otherwise current spike dta is overwritten when loading sorting results.");
                        else
                            SpikeText = strcat("No Existing Internal Spike Data was found");
                        end
    
                        SpikeExtractiontext = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";
                        SpikeExtractiontext2 = "'Filter Double Spike Indicies' is looking for spikes indicies part of the same waveform. 'Time Offset to Combine SpikeIndicies' is the length of that waveform and is added to each spike time. When the next spike is within the current spike time plus the time offset, they get combined to one spike.";
                        textboxx = [SpikeText;"";textboxx;"";SpikeExtractiontext;"";SpikeExtractiontext2];
    
                        app.TextArea.Value = textboxx;
                    end
                end
            end

            %% PostProcessing
            if strcmp(app.OptionsDropDown.Value,"Start New Spike Sorting") || strcmp(app.OptionsDropDown.Value,"Load Sorting Results")
                if strcmp(app.OptionsDropDown.Value,"Start New Spike Sorting") || strcmp(app.OptionsDropDown.Value,"Load Sorting Results")
                    %% Mainapp plot
                    app.Mainapp.UIAxes.NextPlot = "replace"; 
                    plot(app.Mainapp.UIAxes,0,0);
                    app.Mainapp.UIAxes.NextPlot = "add"; 
                    app.Mainapp.UIAxes_2.NextPlot = "replace"; 
                    plot(app.Mainapp.UIAxes_2,0,0);
                    app.Mainapp.UIAxes_2.NextPlot = "add"; 
                    app.Mainapp.UIAxes.Box = "off";
        
                    %Take care of potentially changed backgroundcolor
                    app.Mainapp.UIAxes.Color = app.Mainapp.PlotAppearance.MainWindow.Data.Color.MainBackground;
                    app.Mainapp.UIAxes_2.Color = app.Mainapp.PlotAppearance.MainWindow.Data.Color.TimeBackground;
                    app.Mainapp.UIAxes.FontSize = app.Mainapp.PlotAppearance.MainWindow.Data.MainFontSize;
                    app.Mainapp.UIAxes_2.FontSize = app.Mainapp.PlotAppearance.MainWindow.Data.TimeFontSize;
        
                    if ~isfield(app.Mainapp.Data,'Events')
                        if strcmp(app.Mainapp.PlotEvents,"Events")
                            app.Mainapp.PlotEvents = "No";
                        end
                        Placeholder = {};
                        app.Mainapp.DropDown_2.Items = Placeholder;
                        app.Mainapp.DropDown_2.Items{1} = 'Non';
                    else
                        Placeholder = {};
                        app.Mainapp.DropDown_2.Items = Placeholder;
                        app.Mainapp.DropDown_2.Items{1} = 'Non';
                        app.Mainapp.DropDown_2.Items{2} = 'Events';
                        if strcmp(app.Mainapp.PlotEvents,"Events")
                            app.Mainapp.DropDown_2.Value = 'Events';
                        end
                    end
                
                    if ~isfield(app.Mainapp.Data,'Spikes')
                        % if strcmp(app.Mainapp.Plotspikes,"Spikes")
                        %     app.Mainapp.Plotspikes = "No";
                        % end
                    else
                        app.Mainapp.DropDown_2.Items = Placeholder;
                        app.Mainapp.DropDown_2.Items{1} = 'Non';
                        if isfield(app.Mainapp.Data,'Events')
                            app.Mainapp.DropDown_2.Items{2} = 'Events';
                            app.Mainapp.DropDown_2.Items{3} = 'Spikes';
                        else
                            app.Mainapp.DropDown_2.Items{2} = 'Spikes';
                        end
                        
                        if strcmp(app.Mainapp.PlotEvents,"Events")
                            app.Mainapp.DropDown_2.Value = 'Events';
                        else
                            app.Mainapp.DropDown_2.Value = 'Non';
                        end
                        
                    end
        
                    if strcmp(app.Mainapp.Plotspikes,"Spikes")
                        SpikeHandles = findobj(app.Mainapp.UIAxes, 'Type', 'line', 'Tag', 'Spikes');
                        if ~isempty(SpikeHandles)
                            delete(SpikeHandles(:));
                        end
                        app.Mainapp.Plotspikes = "No";
                    end
        
                    % Update Textbox "Recording Information" in Main window with new preprocessing infos
                    Utility_Show_Info_Loaded_Data(app.Mainapp);
        
                    Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","SpikeExtraction",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
    
                end
            end
        end

        % Value changed function: OptionsDropDown
        function OptionsDropDownValueChanged(app, event)
            if ~strcmp(app.OptionsDropDown.Value,"Start New Spike Sorting")
                app.SortallChannelTogetherCheckBox.Enable = "off";
                app.SortforIndividualChannelCheckBox.Enable = "off";
            else
                app.SortallChannelTogetherCheckBox.Enable = "on";
                app.SortforIndividualChannelCheckBox.Enable = "on";
            end
        end

        % Value changed function: SortallChannelTogetherCheckBox
        function SortallChannelTogetherCheckBoxValueChanged(app, event)
            value = app.SortallChannelTogetherCheckBox.Value;
            if value == 1
                app.SortforIndividualChannelCheckBox.Value = 0;
            end
        end

        % Value changed function: SortforIndividualChannelCheckBox
        function SortforIndividualChannelCheckBoxValueChanged(app, event)
            value = app.SortforIndividualChannelCheckBox.Value;
            if value == 1
                app.SortallChannelTogetherCheckBox.Value = 0;
            end
        end

        % Close request function: SpikeDetectionWindowUIFigure
        function SpikeDetectionWindowUIFigureCloseRequest(app, event)
            app.Mainapp.SpikeExtractionWindow = [];
            delete(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SpikeDetectionWindowUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {807, 807};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpikeDetectionWindowUIFigure = uifigure('Visible', 'off');
            app.SpikeDetectionWindowUIFigure.Name = 'Spike Detection Window';
            app.SpikeDetectionWindowUIFigure.Icon = 'Logo.png';
            app.SpikeDetectionWindowUIFigure.CloseRequestFcn = createCallbackFcn(app, @SpikeDetectionWindowUIFigureCloseRequest, true);
            app.SpikeDetectionWindowUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.SpikeDetectionWindowUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.SpikeFilteringSettingsPanel = uipanel(app.RightPanel);
            app.SpikeFilteringSettingsPanel.Title = 'Spike Filtering Settings';
            app.FilterVerticalSpikeArtefactsCheckBox = uicheckbox(app.SpikeFilteringSettingsPanel);
            app.FilterVerticalSpikeArtefactsCheckBox.ValueChangedFcn = createCallbackFcn(app, @FilterVerticalSpikeArtefactsCheckBoxValueChanged, true);
            app.FilterVerticalSpikeArtefactsCheckBox.Text = {'      Filter Vertical '; '      Spike Artefacts'};
            app.MinDepthofArtefactmEditFieldLabel = uilabel(app.SpikeFilteringSettingsPanel);
            app.MinDepthofArtefactmEditFieldLabel.Text = {'Min. Depth of Artefact'; '[µm]'};
            app.MinDepthofArtefactmEditField = uieditfield(app.SpikeFilteringSettingsPanel, 'text');
            app.MinDepthofArtefactmEditField.ValueChangedFcn = createCallbackFcn(app, @MinDepthofArtefactmEditFieldValueChanged, true);
            app.MinDepthofArtefactmEditField.Value = '200';
            app.VerticalSpikeOffsetToleranceSamplesEditFieldLabel = uilabel(app.SpikeFilteringSettingsPanel);
            app.VerticalSpikeOffsetToleranceSamplesEditFieldLabel.Text = {'Vertical Spike Offset'; 'Tolerance [Samples]'};
            app.VerticalSpikeOffsetToleranceSamplesEditField = uieditfield(app.SpikeFilteringSettingsPanel, 'text');
            app.VerticalSpikeOffsetToleranceSamplesEditField.ValueChangedFcn = createCallbackFcn(app, @VerticalSpikeOffsetToleranceSamplesEditFieldValueChanged, true);
            app.VerticalSpikeOffsetToleranceSamplesEditField.Value = '3';
            app.FilterDoubleSpikeIndiciesCheckBox = uicheckbox(app.SpikeFilteringSettingsPanel);
            app.FilterDoubleSpikeIndiciesCheckBox.Text = {'     Filter Spikes in '; '     Same Waveform'};
            app.FilterDoubleSpikeIndiciesCheckBox.Value = true;
            app.TimeSpantoCombineSpikeIndiciesEditFieldLabel = uilabel(app.SpikeFilteringSettingsPanel);
            app.TimeSpantoCombineSpikeIndiciesEditFieldLabel.Text = {'Time Span to Combine'; 'Spike Indicies'};
            app.TimeOffsettoCombineSpikeIndiciessEditField = uieditfield(app.SpikeFilteringSettingsPanel, 'text');
            app.TimeOffsettoCombineSpikeIndiciessEditField.ValueChangedFcn = createCallbackFcn(app, @TimeOffsettoCombineSpikeIndiciessEditFieldValueChanged, true);
            app.TimeOffsettoCombineSpikeIndiciessEditField.Value = '0.001';
            app.StartSpikeDetectionButton = uibutton(app.SpikeFilteringSettingsPanel, 'push');
            app.StartSpikeDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @StartSpikeDetectionButtonPushed, true);
            app.StartSpikeDetectionButton.Text = {'Start Spike '; 'Detection'};
            app.SpikeDetectionWindowLabel = uilabel(app.RightPanel);
            app.SpikeDetectionWindowLabel.Text = 'Spike Detection Window';
            app.NeuromodLabel = uilabel(app.RightPanel);
            app.NeuromodLabel.Text = 'Neuromod';
            app.CloseButton = uibutton(app.RightPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.TextArea = uitextarea(app.RightPanel);
            app.InformationLabel = uilabel(app.RightPanel);
            app.InformationLabel.Text = 'Information';
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = 'Logo.png';
            app.SpikeDetectionSettingsPanel = uipanel(app.RightPanel);
            app.SpikeDetectionSettingsPanel.Title = 'Spike Detection Settings';
            app.DetectionMethodDropDownLabel = uilabel(app.SpikeDetectionSettingsPanel);
            app.DetectionMethodDropDownLabel.Text = 'Detection Method';
            app.DetectionMethodDropDown = uidropdown(app.SpikeDetectionSettingsPanel);
            app.DetectionMethodDropDown.Items = {'Quiroga Method', 'Threshold: Mean - Std', 'Threshold: Median - Std'};
            app.DetectionMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @DetectionMethodDropDownValueChanged, true);
            app.DetectionMethodDropDown.Value = 'Quiroga Method';
            app.ThresholdEditFieldLabel = uilabel(app.SpikeDetectionSettingsPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.SpikeDetectionSettingsPanel, 'text');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdEditField.Value = '4';
            app.MeanandStdDropDownLabel = uilabel(app.SpikeDetectionSettingsPanel);
            app.MeanandStdDropDownLabel.Text = 'Mean and Std';
            app.MeanDropDown = uidropdown(app.SpikeDetectionSettingsPanel);
            app.MeanDropDown.Items = {'All Channel', 'Individual Ch.'};
            app.MeanDropDown.Value = 'All Channel';
            app.Wave_clus3SpikeSortingSettingsPanel = uipanel(app.RightPanel);
            app.Wave_clus3SpikeSortingSettingsPanel.Title = 'Wave_clus 3 Spike Sorting Settings';
            app.OptionsDropDownLabel = uilabel(app.Wave_clus3SpikeSortingSettingsPanel);
            app.OptionsDropDownLabel.Text = 'Options';
            app.OptionsDropDown = uidropdown(app.Wave_clus3SpikeSortingSettingsPanel);
            app.OptionsDropDown.Items = {'Start New Spike Sorting', 'Change Sorting Parameters', 'Load Sorting Results', 'Manually Select Folder with Sorting Results'};
            app.OptionsDropDown.ValueChangedFcn = createCallbackFcn(app, @OptionsDropDownValueChanged, true);
            app.OptionsDropDown.Value = 'Start New Spike Sorting';
            app.SortforIndividualChannelCheckBox = uicheckbox(app.Wave_clus3SpikeSortingSettingsPanel);
            app.SortforIndividualChannelCheckBox.ValueChangedFcn = createCallbackFcn(app, @SortforIndividualChannelCheckBoxValueChanged, true);
            app.SortforIndividualChannelCheckBox.Text = '    Sort for Individual Channel';
            app.SortallChannelTogetherCheckBox = uicheckbox(app.Wave_clus3SpikeSortingSettingsPanel);
            app.SortallChannelTogetherCheckBox.ValueChangedFcn = createCallbackFcn(app, @SortallChannelTogetherCheckBoxValueChanged, true);
            app.SortallChannelTogetherCheckBox.Text = '    Sort all Channel Together';
            app.SortallChannelTogetherCheckBox.Value = true;
            app.RUNButton = uibutton(app.Wave_clus3SpikeSortingSettingsPanel, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            % Show the figure after all components are created
            app.SpikeDetectionWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Spike_Detection_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SpikeDetectionWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.SpikeDetectionWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpikeDetectionWindowUIFigure)
        end
    end
end