classdef Spike_Detection_and_Sorting_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpikeDetectionWindowUIFigure    matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        RightPanel                      matlab.ui.container.Panel
        InformationLabel_2              matlab.ui.control.Label
        TextArea_2                      matlab.ui.control.TextArea
        Wave_clus3SpikeSortingPanel     matlab.ui.container.Panel
        NotrecommendedLabel_2           matlab.ui.control.Label
        SetOutputFolderManuallyButton_2  matlab.ui.control.Button
        RunsviaMatlabLabel              matlab.ui.control.Label
        RUNButton                       matlab.ui.control.Button
        SortallChannelTogetherCheckBox  matlab.ui.control.CheckBox
        SortforIndividualChannelCheckBox  matlab.ui.control.CheckBox
        OptionsDropDown                 matlab.ui.control.DropDown
        OptionsDropDownLabel            matlab.ui.control.Label
        InternalSpikeDetectionSettingsPanel  matlab.ui.container.Panel
        StartSpikeDetectionButton       matlab.ui.control.Button
        TimeOffsettoCombineSpikeIndiciessEditField  matlab.ui.control.EditField
        TimeSpantoCombineSpikeIndiciesEditFieldLabel  matlab.ui.control.Label
        FilterDoubleSpikeIndiciesCheckBox  matlab.ui.control.CheckBox
        MinDepthofArtefactmEditField    matlab.ui.control.EditField
        MinDepthofArtefactmEditFieldLabel  matlab.ui.control.Label
        VerticalSpikeOffsetToleranceSamplesEditField  matlab.ui.control.EditField
        VerticalSpikeOffsetToleranceSamplesEditFieldLabel  matlab.ui.control.Label
        FilterVerticalSpikeArtefactsCheckBox  matlab.ui.control.CheckBox
        MeanDropDown                    matlab.ui.control.DropDown
        MeanandStdDropDownLabel         matlab.ui.control.Label
        ThresholdEditField              matlab.ui.control.EditField
        ThresholdEditFieldLabel         matlab.ui.control.Label
        DetectionMethodDropDown         matlab.ui.control.DropDown
        DetectionMethodLabel            matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        InformationLabel                matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        CloseButton                     matlab.ui.control.Button
        NeuromodLabel                   matlab.ui.control.Label
        SpikeDetectionandSortingLabel   matlab.ui.control.Label
        SpikeInterfaceSpikeSortingPanel  matlab.ui.container.Panel
        CheckBox                        matlab.ui.control.CheckBox
        Label                           matlab.ui.control.Label
        NotrecommendedLabel             matlab.ui.control.Label
        SetOutputFolderManuallyButton   matlab.ui.control.Button
        OptionsDropDown_2               matlab.ui.control.DropDown
        OptionsDropDown_2Label          matlab.ui.control.Label
        RunsviaPythonLabel              matlab.ui.control.Label
        RUNButton_2                     matlab.ui.control.Button
        SorterDropDown                  matlab.ui.control.DropDown
        SorterDropDownLabel             matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Mainapp = []; % Description
        SpikeSortingPath = [];
        AutoSortingPathToBin = [];
        SpikeSortinBinPath = [];
        SpikeInterfaceParameter = [];
        SortingParameterWindow = [];
        ManualSelection = [];

        ParameterStructure = []; % For spike sorting settings mountainsort 5 und spykingcircus 2
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            movegui(app.SpikeDetectionWindowUIFigure, 'center');

            app.Mainapp = Mainapp;
            
            app.ManualSelection = 0;

            if isfield(app.Mainapp.Data,'Spikes') && strcmp(app.Mainapp.Data.Info.SpikeType,"Internal")
                SpikeText = strcat("Existing Spike Data was found with threshold ",num2str(app.Mainapp.Data.Info.SpikeDetectionNrStd));
            else
                SpikeText = strcat("No Existing Internal Spike Data was found");
            end

            app.VerticalSpikeOffsetToleranceSamplesEditField.Enable = "off";
            app.MinDepthofArtefactmEditField.Enable = "off";

            % Check if spike sorting was already saved
            % Check if the folder exists
            app.SpikeSortingPath = strcat(app.Mainapp.Data.Info.Data_Path,'\Wave_Clus');
            
            cluster_class = [];

            if isfield(app.Mainapp.Data,'Spikes') && strcmp(app.Mainapp.Data.Info.SpikeType,'Internal')
                SpikeText = strcat("Spike data already part of dataset with threshold ",num2str(app.Mainapp.Data.Info.SpikeDetectionNrStd),". If you want to load spike sorting data make sure the threshold is the same. Otherwise current spike dta is overwritten when loading sorting results.");
            else
                SpikeText = strcat("No Existing Internal Spike Data was found");
            end
            
            if ~isfolder(app.SpikeSortingPath) % No sorting found
                textboxx = strcat("Path: ",app.SpikeSortingPath," does not contain spike sorting results folder or path is not available anymore. Eihter start a new sorting or load a different folder");
                SpikeExtractiontext = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";
                SpikeExtractiontext2 = "'Filter Double Spike Indicies' is looking for spikes indicies part of the same waveform. 'Time Offset to Combine SpikeIndicies' is the length of that waveform and is added to each spike time. When the next spike is within the current spike time plus the time offset, they get combined to one spike.";
                textboxx = [SpikeText;"";SpikeExtractiontext;"";SpikeExtractiontext2;"";textboxx];
                app.TextArea.Value = textboxx;
            else

                textboxx = strcat("Spike Sorting Data found in: ",app.SpikeSortingPath,". Select the 'Load Sorting Results' option and press 'RUN' to load spike sorting.");

                if isfile(strcat(strcat(app.SpikeSortingPath,'\times_spikes.mat')))
                    load(strcat(strcat(app.SpikeSortingPath,'\times_spikes.mat')));
                    SortingType = "AllChannel";
                    textboxx = [textboxx;"";"Type: Sorted all channel together."];
                else
                    if isfile(strcat(strcat(app.SpikeSortingPath,'\times_CH',num2str(1),'.mat')))
                        load(strcat(strcat(app.SpikeSortingPath,'\times_CH',num2str(1),'.mat')));
                        SortingType = "IndividualChannel";
                        textboxx = [textboxx;"";"Type: Sorted channel individually."];
                    else
                        textboxx = strcat("Path: ",app.SpikeSortingPath," does not contain spike sorting results folder or path is not available anymore. Eihter start a new sorting or load a different folder");
                        SpikeExtractiontext = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";
                        SpikeExtractiontext2 = "'Filter Double Spike Indicies' is looking for spikes indicies part of the same waveform. 'Time Offset to Combine SpikeIndicies' is the length of that waveform and is added to each spike time. When the next spike is within the current spike time plus the time offset, they get combined to one spike.";
                        textboxx = [SpikeText;"";SpikeExtractiontext;"";SpikeExtractiontext2;"";textboxx];
                        app.TextArea.Value = textboxx;
                        return;
                    end
                end

                if strcmp(SortingType,"AllChannel")
                    numclu = length(unique(cluster_class(:,1)));
    
                    textboxx = [textboxx;" ";strcat("Number of Cluster: ",num2str(numclu));""];
                    
                    % Initialize a string array to store the output
                    outputText = "";
                    
                    % Get all field names from the structure
                    fields = fieldnames(par);
    
                    % Loop through each field and concatenate the field name and value
                    for i = 1:numel(fields)
                        fieldName = fields{i};
                        fieldValue = par.(fieldName);
                        
                        % Convert the field value to a string if it is not already
                        if isnumeric(fieldValue)
                            fieldValue = num2str(fieldValue);
                        elseif ischar(fieldValue)
                            fieldValue = string(fieldValue);
                        end
                        
                        % Concatenate the field name and value
                        outputText = outputText + fieldName + ": " + fieldValue + newline;
                    end
                    
                    % Set the TextArea value in the app (assuming app.TextArea is the name of the TextArea)
                    textboxx = [textboxx;outputText];
                    SpikeExtractiontext = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";
                    SpikeExtractiontext2 = "'Filter Double Spike Indicies' is looking for spikes indicies part of the same waveform. 'Time Offset to Combine SpikeIndicies' is the length of that waveform and is added to each spike time. When the next spike is within the current spike time plus the time offset, they get combined to one spike.";
                    textboxx = [SpikeText;"";SpikeExtractiontext;"";SpikeExtractiontext2;"";textboxx;outputText];
    
                    app.TextArea.Value = textboxx;
                else
                    if isfield(app.Mainapp.Data,'Spikes') && strcmp(app.Mainapp.Data.Info.SpikeType,'Internal')
                        SpikeText = strcat("Spike data already part of dataset with threshold ",num2str(app.Mainapp.Data.Info.SpikeDetectionNrStd),". If you want to load spike sorting data make sure the threshold is the same. Otherwise current spike dta is overwritten when loading sorting results.");
                    else
                        SpikeText = strcat("No Existing Internal Spike Data was found");
                    end

                    SpikeExtractiontext = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";
                    SpikeExtractiontext2 = "'Filter Double Spike Indicies' is looking for spikes indicies part of the same waveform. 'Time Offset to Combine SpikeIndicies' is the length of that waveform and is added to each spike time. When the next spike is within the current spike time plus the time offset, they get combined to one spike.";
                    textboxx = [SpikeText;"";textboxx;"";SpikeExtractiontext;"";SpikeExtractiontext2];

                    app.TextArea.Value = textboxx;
                end
            end
           

            %% Check if SpikeInterface standard folder found
            StandardPath = strcat(app.Mainapp.Data.Info.Data_Path,'\SpikeInterface');
            [stringArray] = Utility_Extract_Contents_of_Folder(StandardPath);

            if sum(contains(stringArray,'.bin'))>0
                a = find(contains(stringArray,'.bin')==1);
                if length(a)>1 || isempty(a)
                    disp(strcat("More than one .bin file found in autosearched directory. Autosearched path can not be taken for sorting. Please select a .bin file manually!.",StandardPath))
                    app.CheckBox.Value = 0;
                    app.Label.FontColor = [1.00,0.00,0.00];
                    app.AutoSortingPathToBin = 0;
                    app.Label.Text = "Auto-detection of exported .bin file " + ...
                        "NOT Succesfull";
                    app.SpikeSortinBinPath = [];
                else
                    disp(strcat(".bin found in autosearched directory ",StandardPath))
                    app.CheckBox.Value = 1;
                    app.Label.FontColor = [0.47,0.67,0.19];
                    app.AutoSortingPathToBin = 1;
                    app.Label.Text = "Auto-detection of exported .bin file " + ...
                        "Succesfull";

                    app.SpikeSortinBinPath = strcat(StandardPath,'\',stringArray(a));
                end
            else
                disp(strcat("More than one .bin file found in autosearched directory. Autosearched path can not be taken for sorting. Please select a .bin file manually!.",StandardPath))
                app.CheckBox.Value = 0;
                app.Label.FontColor = [1.00,0.00,0.00];
                app.AutoSortingPathToBin = 0;
                app.Label.Text = "Auto-detection of exported .bin file " + ...
                    "NOT Succesfull";
                app.SpikeSortinBinPath = [];
            end
        end

        % Button pushed function: StartSpikeDetectionButton
        function StartSpikeDetectionButtonPushed(app, event)
            %% Check if high pass filtered data is available. If not, display error message and return
            if ~isfield(app.Mainapp.Data,'Preprocessed')
                msgbox("Error: No high pass filtered data detected. Spike detection on raw data wont work. High pass filter data and open this window again!");
                disp("Error: No high pass filtered data detected. Spike detection on raw data wont work. High pass filter data and open this window again!");
                return;
            else
                if ~isfield(app.Mainapp.Data.Info,'FilterMethod')
                    msgbox("Error: No high pass filtered data detected. Spike detection on raw data wont work. High pass filter data and open this window again!");
                    disp("Error: No high pass filtered data detected. Spike detection on raw data wont work. High pass filter data and open this window again!");
                    return;
                else
                    if ~strcmp(app.Mainapp.Data.Info.FilterMethod,"High-Pass")
                        msgbox("Error: No high pass filtered data detected. Spike detection on raw data wont work. High pass filter data and open this window again!");
                        disp("Error: No high pass filtered data detected. Spike detection on raw data wont work. High pass filter data and open this window again!");
                        return;
                    end
                end
            end

            disp(strcat("Extracting Spikes. Please wait until the progress bar closes"));

            %% Spike Detection
            [app.Mainapp.Data,~] = Spike_Module_Spike_Detection(app.Mainapp.Data,app.DetectionMethodDropDown.Value,app.MeanDropDown.Value,str2double(app.ThresholdEditField.Value),app.FilterVerticalSpikeArtefactsCheckBox.Value,app.VerticalSpikeOffsetToleranceSamplesEditField.Value,app.MinDepthofArtefactmEditField.Value,app.FilterDoubleSpikeIndiciesCheckBox.Value,str2double(app.TimeOffsettoCombineSpikeIndiciessEditField.Value));

            %% Aftmeramth: Resetting Main window Plots and plot data again
            disp(strcat("Spike detection finished. They can now be visualized and analyzed in the main window"));

            app.Mainapp.UIAxes.NextPlot = "replace"; 
            plot(app.Mainapp.UIAxes,0,0);
            app.Mainapp.UIAxes.NextPlot = "add"; 
            app.Mainapp.UIAxes_2.NextPlot = "replace"; 
            plot(app.Mainapp.UIAxes_2,0,0);
            app.Mainapp.UIAxes_2.NextPlot = "add"; 
            app.Mainapp.UIAxes.Box = "off";

            %Take care of potentially changed backgroundcolor
            app.Mainapp.UIAxes.Color = app.Mainapp.PlotAppearance.MainWindow.Data.Color.MainBackground;
            app.Mainapp.UIAxes_2.Color = app.Mainapp.PlotAppearance.MainWindow.Data.Color.TimeBackground;
            app.Mainapp.UIAxes.FontSize = app.Mainapp.PlotAppearance.MainWindow.Data.MainFontSize;
            app.Mainapp.UIAxes_2.FontSize = app.Mainapp.PlotAppearance.MainWindow.Data.TimeFontSize;

            if ~isfield(app.Mainapp.Data,'Events')
                if strcmp(app.Mainapp.PlotEvents,"Events")
                    app.Mainapp.PlotEvents = "No";
                end
                Placeholder = {};
                app.Mainapp.DropDown_2.Items = Placeholder;
                app.Mainapp.DropDown_2.Items{1} = 'Non';
            else
                Placeholder = {};
                app.Mainapp.DropDown_2.Items = Placeholder;
                app.Mainapp.DropDown_2.Items{1} = 'Non';
                app.Mainapp.DropDown_2.Items{2} = 'Events';
                if strcmp(app.Mainapp.PlotEvents,"Events")
                    app.Mainapp.DropDown_2.Value = 'Events';
                end
            end
        
            if ~isfield(app.Mainapp.Data,'Spikes')
                % if strcmp(app.Mainapp.Plotspikes,"Spikes")
                %     app.Mainapp.Plotspikes = "No";
                % end
            else
                app.Mainapp.DropDown_2.Items = Placeholder;
                app.Mainapp.DropDown_2.Items{1} = 'Non';
                if isfield(app.Mainapp.Data,'Events')
                    app.Mainapp.DropDown_2.Items{2} = 'Events';
                    app.Mainapp.DropDown_2.Items{3} = 'Spikes';
                else
                    app.Mainapp.DropDown_2.Items{2} = 'Spikes';
                end
                
                if strcmp(app.Mainapp.PlotEvents,"Events")
                    app.Mainapp.DropDown_2.Value = 'Events';
                else
                    app.Mainapp.DropDown_2.Value = 'Non';
                end
            end

            if strcmp(app.Mainapp.Plotspikes,"Spikes")
                SpikeHandles = findobj(app.Mainapp.UIAxes, 'Type', 'line', 'Tag', 'Spikes');
                if ~isempty(SpikeHandles)
                    delete(SpikeHandles(:));
                end
                app.Mainapp.Plotspikes = "No";
            end

            Utility_Show_Info_Loaded_Data(app.Mainapp);

            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","SpikeExtraction",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);

            %% Fill GUI textbox with new data
            % Check if spike sorting was already saved
            % Check if the folder exists
            if isfield(app.Mainapp.Data,'Spikes') && strcmp(app.Mainapp.Data.Info.SpikeType,"Internal")
                SpikeText = strcat("Existing Spike Data was found with threshold ",num2str(app.Mainapp.Data.Info.SpikeDetectionNrStd));
            else
                SpikeText = strcat("No Existing Internal Spike Data was found");
            end

            app.SpikeSortingPath = strcat(app.Mainapp.Data.Info.Data_Path,'\Wave_Clus');
            
            if isfield(app.Mainapp.Data,'Spikes') && strcmp(app.Mainapp.Data.Info.SpikeType,'Internal')
                SpikeText = strcat("Spike data already part of dataset with threshold ",num2str(app.Mainapp.Data.Info.SpikeDetectionNrStd),". If you want to load spike sorting data make sure the threshold is the same. Otherwise current spike dta is overwritten when loading sorting results.");
            else
                SpikeText = strcat("No Existing Internal Spike Data was found");
            end
            
            if ~isfolder(app.SpikeSortingPath) % No sorting found
                textboxx = strcat("Path: ",app.SpikeSortingPath," does not contain spike sorting results folder or path is not available anymore. Eihter start a new sorting or load a different folder");
                SpikeExtractiontext = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";
                SpikeExtractiontext2 = "'Filter Double Spike Indicies' is looking for spikes indicies part of the same waveform. 'Time Offset to Combine SpikeIndicies' is the length of that waveform and is added to each spike time. When the next spike is within the current spike time plus the time offset, they get combined to one spike.";
                    textboxx = [SpikeText;"";SpikeExtractiontext;"";SpikeExtractiontext2;"";textboxx];
                app.TextArea.Value = textboxx;
            else

                textboxx = strcat("Spike Sorting Data found in: ",app.SpikeSortingPath,". Select the 'Load Sorting Results' option and press 'RUN' to load spike sorting.");

                if isfile(strcat(strcat(app.SpikeSortingPath,'\times_spikes.mat')))
                    load(strcat(strcat(app.SpikeSortingPath,'\times_spikes.mat')));
                    SortingType = "AllChannel";
                    textboxx = [textboxx;"";"Type: Sorted all channel together."];
                else
                    if isfile(strcat(strcat(app.SpikeSortingPath,'\times_CH',num2str(1),'.mat')))
                        load(strcat(strcat(app.SpikeSortingPath,'\times_CH',num2str(1),'.mat')));
                        SortingType = "IndividualChannel";
                        textboxx = [textboxx;"";"Type: Sorted channel individually."];
                    else
                        textboxx = strcat("Path: ",app.SpikeSortingPath," does not contain spike sorting results folder or path is not available anymore. Eihter start a new sorting or load a different folder");
                        SpikeExtractiontext = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";
                        SpikeExtractiontext2 = "'Filter Double Spike Indicies' is looking for spikes indicies part of the same waveform. 'Time Offset to Combine SpikeIndicies' is the length of that waveform and is added to each spike time. When the next spike is within the current spike time plus the time offset, they get combined to one spike.";
                        textboxx = [SpikeText;"";SpikeExtractiontext;"";SpikeExtractiontext2;"";textboxx];
                        app.TextArea.Value = textboxx;
                        return;
                    end
                end

                if strcmp(SortingType,"AllChannel")
                    numclu = length(unique(cluster_class(:,1)));
    
                    textboxx = [textboxx;" ";strcat("Number of Cluster: ",num2str(numclu));""];
                    
                    % Initialize a string array to store the output
                    outputText = "";
                    
                    % Get all field names from the structure
                    fields = fieldnames(par);
    
                    % Loop through each field and concatenate the field name and value
                    for i = 1:numel(fields)
                        fieldName = fields{i};
                        fieldValue = par.(fieldName);
                        
                        % Convert the field value to a string if it is not already
                        if isnumeric(fieldValue)
                            fieldValue = num2str(fieldValue);
                        elseif ischar(fieldValue)
                            fieldValue = string(fieldValue);
                        end
                        
                        % Concatenate the field name and value
                        outputText = outputText + fieldName + ": " + fieldValue + newline;
                    end
                    
                    % Set the TextArea value in the app (assuming app.TextArea is the name of the TextArea)
                    textboxx = [textboxx;outputText];
                    SpikeExtractiontext = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";
                    SpikeExtractiontext2 = "'Filter Double Spike Indicies' is looking for spikes indicies part of the same waveform. 'Time Offset to Combine SpikeIndicies' is the length of that waveform and is added to each spike time. When the next spike is within the current spike time plus the time offset, they get combined to one spike.";
                    textboxx = [SpikeText;"";SpikeExtractiontext;"";SpikeExtractiontext2;"";textboxx;outputText];
    
                    app.TextArea.Value = textboxx;
                else
                    if isfield(app.Mainapp.Data,'Spikes') && strcmp(app.Mainapp.Data.Info.SpikeType,'Internal')
                        SpikeText = strcat("Spike data already part of dataset with threshold ",num2str(app.Mainapp.Data.Info.SpikeDetectionNrStd),". If you want to load spike sorting data make sure the threshold is the same. Otherwise current spike dta is overwritten when loading sorting results.");
                    else
                        SpikeText = strcat("No Existing Internal Spike Data was found");
                    end

                    SpikeExtractiontext = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";
                    SpikeExtractiontext2 = "'Filter Double Spike Indicies' is looking for spikes indicies part of the same waveform. 'Time Offset to Combine SpikeIndicies' is the length of that waveform and is added to each spike time. When the next spike is within the current spike time plus the time offset, they get combined to one spike.";
                    textboxx = [SpikeText;"";textboxx;"";SpikeExtractiontext;"";SpikeExtractiontext2];

                    app.TextArea.Value = textboxx;
                end
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.Mainapp.SpikeExtractionWindow = [];
            if ~isempty(app.SortingParameterWindow)
                delete(app.SortingParameterWindow)
            end

            delete(app);
        end

        % Value changed function: DetectionMethodDropDown
        function DetectionMethodDropDownValueChanged(app, event)
            
        end

        % Value changed function: FilterVerticalSpikeArtefactsCheckBox
        function FilterVerticalSpikeArtefactsCheckBoxValueChanged(app, event)
            value = app.FilterVerticalSpikeArtefactsCheckBox.Value;
            if value == 1
                app.VerticalSpikeOffsetToleranceSamplesEditField.Enable = "on";
                app.MinDepthofArtefactmEditField.Enable = "on";
            else
                app.VerticalSpikeOffsetToleranceSamplesEditField.Enable = "off";
                app.MinDepthofArtefactmEditField.Enable = "off";
            end
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            [app.ThresholdEditField.Value] = Utility_SimpleCheckInputs(app.ThresholdEditField.Value,"One",'4',0,0);
            
        end

        % Value changed function: 
        % VerticalSpikeOffsetToleranceSamplesEditField
        function VerticalSpikeOffsetToleranceSamplesEditFieldValueChanged(app, event)
            [app.VerticalSpikeOffsetToleranceSamplesEditField.Value] = Utility_SimpleCheckInputs(app.VerticalSpikeOffsetToleranceSamplesEditField.Value,"One",'3',0,0);
            
        end

        % Value changed function: MinDepthofArtefactmEditField
        function MinDepthofArtefactmEditFieldValueChanged(app, event)
            [app.MinDepthofArtefactmEditField.Value] = Utility_SimpleCheckInputs(app.MinDepthofArtefactmEditField.Value,"One",'200',0,0);
            
        end

        % Value changed function: 
        % TimeOffsettoCombineSpikeIndiciessEditField
        function TimeOffsettoCombineSpikeIndiciessEditFieldValueChanged(app, event)
            
            [app.TimeOffsettoCombineSpikeIndiciessEditField.Value] = Utility_SimpleCheckInputs(app.TimeOffsettoCombineSpikeIndiciessEditField.Value,"One",'0.002',0,0);
            
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            %% New Spike Sorting
            if strcmp(app.OptionsDropDown.Value,"Start New Spike Sorting")
                if isfield(app.Mainapp.Data,'Spikes') && strcmp(app.Mainapp.Data.Info.SpikeType,'Internal')
                    if isfield(app.Mainapp.Data,'Preprocessed')
                        if isfield(app.Mainapp.Data.Info,'FilterMethod')
                            if strcmp(app.Mainapp.Data.Info.FilterMethod,"High-Pass")
                                if app.SortallChannelTogetherCheckBox.Value == 1 
                                    SortingType = "AllChannelTogether";
                                elseif app.SortforIndividualChannelCheckBox.Value == 1
                                    SortingType = "IndividualChannel";
                                end

                                [app.Mainapp.Data] = Spike_Module_Internal_Spike_Sorting(app.Mainapp.Data,app.SpikeSortingPath,"Clustering",SortingType);
                            else
                                msgbox("No High Pass Filtered Data found! Please first apply a high pass filter to obtain proper waveforms!")
                                return;
                            end
                        else
                            msgbox("No High Pass Filtered Data found! Please first apply a high pass filter to obtain proper waveforms!")
                            return;
                        end
                    else
                        msgbox("No High Pass Filtered Data found! Please first apply a high pass filter to obtain proper waveforms!")
                        return;
                    end
                else
                    msgbox("No Internal Spike Data found. Please first Start Spike Detection!")
                    return;
                end
             %% Change Sorting Parameters
            elseif strcmp(app.OptionsDropDown.Value,"Change Sorting Parameters")
                set_parameters_ui();
            %% Load Sorting
            elseif strcmp(app.OptionsDropDown.Value,"Load Sorting Results")
                if isfield(app.Mainapp.Data,'Spikes') && strcmp(app.Mainapp.Data.Info.SpikeType,'Internal')
                    if isfield(app.Mainapp.Data,'Preprocessed')
                        if isfield(app.Mainapp.Data.Info,'FilterMethod')
                            if strcmp(app.Mainapp.Data.Info.FilterMethod,"High-Pass")
                                [app.Mainapp.Data] = Spike_Module_Internal_Spike_Sorting(app.Mainapp.Data,app.SpikeSortingPath,"Loading");
                            else
                                msgbox("No High Pass Filtered Data found! Please first apply a high pass filter to obtain proper waveforms!")
                                return;
                            end
                        else
                            msgbox("No High Pass Filtered Data found! Please first apply a high pass filter to obtain proper waveforms!")
                            return;
                        end
                    else
                        msgbox("No High Pass Filtered Data found! Please first apply a high pass filter to obtain proper waveforms!")
                        return;
                    end
                else
                    [app.Mainapp.Data] = Spike_Module_Internal_Spike_Sorting(app.Mainapp.Data,app.SpikeSortingPath,"Loading");
                end
            %% Manually select folder with sorting results
            elseif strcmp(app.OptionsDropDown.Value,"Manually Select Folder with Sorting Results")

                
            end

            %% PostProcessing
            if strcmp(app.OptionsDropDown.Value,"Start New Spike Sorting") || strcmp(app.OptionsDropDown.Value,"Load Sorting Results")
                if strcmp(app.OptionsDropDown.Value,"Start New Spike Sorting") || strcmp(app.OptionsDropDown.Value,"Load Sorting Results")
                    %% Mainapp plot
                    app.Mainapp.UIAxes.NextPlot = "replace"; 
                    plot(app.Mainapp.UIAxes,0,0);
                    app.Mainapp.UIAxes.NextPlot = "add"; 
                    app.Mainapp.UIAxes_2.NextPlot = "replace"; 
                    plot(app.Mainapp.UIAxes_2,0,0);
                    app.Mainapp.UIAxes_2.NextPlot = "add"; 
                    app.Mainapp.UIAxes.Box = "off";
        
                    %Take care of potentially changed backgroundcolor
                    app.Mainapp.UIAxes.Color = app.Mainapp.PlotAppearance.MainWindow.Data.Color.MainBackground;
                    app.Mainapp.UIAxes_2.Color = app.Mainapp.PlotAppearance.MainWindow.Data.Color.TimeBackground;
                    app.Mainapp.UIAxes.FontSize = app.Mainapp.PlotAppearance.MainWindow.Data.MainFontSize;
                    app.Mainapp.UIAxes_2.FontSize = app.Mainapp.PlotAppearance.MainWindow.Data.TimeFontSize;
        
                    if ~isfield(app.Mainapp.Data,'Events')
                        if strcmp(app.Mainapp.PlotEvents,"Events")
                            app.Mainapp.PlotEvents = "No";
                        end
                        Placeholder = {};
                        app.Mainapp.DropDown_2.Items = Placeholder;
                        app.Mainapp.DropDown_2.Items{1} = 'Non';
                    else
                        Placeholder = {};
                        app.Mainapp.DropDown_2.Items = Placeholder;
                        app.Mainapp.DropDown_2.Items{1} = 'Non';
                        app.Mainapp.DropDown_2.Items{2} = 'Events';
                        if strcmp(app.Mainapp.PlotEvents,"Events")
                            app.Mainapp.DropDown_2.Value = 'Events';
                        end
                    end
                
                    if ~isfield(app.Mainapp.Data,'Spikes')
                        % if strcmp(app.Mainapp.Plotspikes,"Spikes")
                        %     app.Mainapp.Plotspikes = "No";
                        % end
                    else
                        app.Mainapp.DropDown_2.Items = Placeholder;
                        app.Mainapp.DropDown_2.Items{1} = 'Non';
                        if isfield(app.Mainapp.Data,'Events')
                            app.Mainapp.DropDown_2.Items{2} = 'Events';
                            app.Mainapp.DropDown_2.Items{3} = 'Spikes';
                        else
                            app.Mainapp.DropDown_2.Items{2} = 'Spikes';
                        end
                        
                        if strcmp(app.Mainapp.PlotEvents,"Events")
                            app.Mainapp.DropDown_2.Value = 'Events';
                        else
                            app.Mainapp.DropDown_2.Value = 'Non';
                        end
                        
                    end
        
                    if strcmp(app.Mainapp.Plotspikes,"Spikes")
                        SpikeHandles = findobj(app.Mainapp.UIAxes, 'Type', 'line', 'Tag', 'Spikes');
                        if ~isempty(SpikeHandles)
                            delete(SpikeHandles(:));
                        end
                        app.Mainapp.Plotspikes = "No";
                    end
        
                    % Update Textbox "Recording Information" in Main window with new preprocessing infos
                    Utility_Show_Info_Loaded_Data(app.Mainapp);
        
                    Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","SpikeExtraction",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
    
                end
            end
        end

        % Value changed function: OptionsDropDown
        function OptionsDropDownValueChanged(app, event)
            if ~strcmp(app.OptionsDropDown.Value,"Start New Spike Sorting")
                app.SortallChannelTogetherCheckBox.Enable = "off";
                app.SortforIndividualChannelCheckBox.Enable = "off";
            else
                app.SortallChannelTogetherCheckBox.Enable = "on";
                app.SortforIndividualChannelCheckBox.Enable = "on";
            end
        end

        % Value changed function: SortallChannelTogetherCheckBox
        function SortallChannelTogetherCheckBoxValueChanged(app, event)
            value = app.SortallChannelTogetherCheckBox.Value;
            if value == 1
                app.SortforIndividualChannelCheckBox.Value = 0;
            end
        end

        % Value changed function: SortforIndividualChannelCheckBox
        function SortforIndividualChannelCheckBoxValueChanged(app, event)
            value = app.SortforIndividualChannelCheckBox.Value;
            if value == 1
                app.SortallChannelTogetherCheckBox.Value = 0;
            end
        end

        % Close request function: SpikeDetectionWindowUIFigure
        function SpikeDetectionWindowUIFigureCloseRequest(app, event)
            app.Mainapp.SpikeExtractionWindow = [];
            if ~isempty(app.SortingParameterWindow)
                delete(app.SortingParameterWindow)
            end
            delete(app);
        end

        % Button pushed function: RUNButton_2
        function RUNButton_2Pushed(app, event)
            
            app.SpikeSortinBinPath = convertStringsToChars(app.SpikeSortinBinPath);

            if isempty(app.SpikeSortinBinPath) && strcmp(app.OptionsDropDown_2.Value,"Start New Spike Sorting")
                msgbox("Error: No valid path containing a .bin file of your recording found. Please first export dataset as a .bin file using the 'Save for Sorting' option or specify the path to that file manually.")
                return;
            end

            if strcmp(app.OptionsDropDown_2.Value,"Start New Spike Sorting")

                [pythonPath] = Spike_Module_Check_Load_Conda_Python_exe(app.Mainapp.executableFolder);
                SpikeInterfaceScriptPath = strcat(app.Mainapp.executableFolder,'\Modules\SpikeInterface\SpikeInterface_Sorting.py');
                
                if isempty(app.SpikeInterfaceParameter)
                    app.SpikeInterfaceParameter.OpenSpikeInterface = 1;
                    app.SpikeInterfaceParameter.Preprocess = 1;
                    app.SpikeInterfaceParameter.PlotTraces = 0;
                    app.SpikeInterfaceParameter.PlotSortingResults = 0;
                    app.SpikeInterfaceParameter.JustOpenSpikeInterfaceGUI = 0;
                    app.SpikeInterfaceParameter.LoadSorting = 0;
                    app.SpikeInterfaceParameter.MultipleRecordings = 0;
                    app.SpikeInterfaceParameter.Sorter = app.SorterDropDown.Value;
                    app.SpikeInterfaceParameter.KeepConsoleOpen = 0;
                else
                    app.SpikeInterfaceParameter.JustOpenSpikeInterfaceGUI = 0;
                end

                % path
                if app.ManualSelection == 0
                    dashindex = find(app.SpikeSortinBinPath=='\');
                    TempSpikeSortinBinPath = app.SpikeSortinBinPath(1:dashindex(end)-1);
                    file_path = TempSpikeSortinBinPath;
                else
                    if app.SpikeInterfaceParameter.MultipleRecordings == 0
                        dashindex = find(app.SpikeSortinBinPath=='\');
                        TempSpikeSortinBinPath = app.SpikeSortinBinPath(1:dashindex(end)-1);
                        file_path = TempSpikeSortinBinPath;
                    else
                        file_path = app.SpikeSortinBinPath;
                    end
                end

                SampleRate = app.Mainapp.Data.Info.NativeSamplingRate;
                NumChannel = size(app.Mainapp.Data.Raw,1);
                ypitch = app.Mainapp.Data.Info.ChannelSpacing;

                % Build the command string
                VerChannelOffset = app.Mainapp.Data.Info.ProbeInfo.VertOffset;
                HorChannelOffset = app.Mainapp.Data.Info.ProbeInfo.HorOffset;

                if strcmp(app.SorterDropDown.Value,"Mountainsort 5")
                    % Convert Sorter Parameter into dictionary
                    SortingParameters = jsonencode(app.ParameterStructure.MS5);
                else
                    % Convert Sorter Parameter into dictionary
                    SortingParameters = jsonencode(app.ParameterStructure.CS2);
                end

                % Save JSON to a temporary file
                jsonFilePath = fullfile(file_path, 'sorting_parameters.json');
                fid = fopen(jsonFilePath, 'w');
                fwrite(fid, SortingParameters, 'char');
                fclose(fid);

                command = sprintf('"%s" "%s" "%s" %d "%s" %d %d %d %d %d %d %d %d %d', ...
                    pythonPath, SpikeInterfaceScriptPath, file_path, app.SpikeInterfaceParameter.MultipleRecordings, app.SpikeInterfaceParameter.Sorter, ...
                    app.SpikeInterfaceParameter.Preprocess, app.SpikeInterfaceParameter.LoadSorting, app.SpikeInterfaceParameter.OpenSpikeInterface, ...
                    app.SpikeInterfaceParameter.PlotSortingResults, app.SpikeInterfaceParameter.JustOpenSpikeInterfaceGUI, SampleRate, NumChannel, ypitch, app.SpikeInterfaceParameter.KeepConsoleOpen);
                
                % Execute the Python script
                [status, cmdout] = system(command);
                
                % Check status

                if status == 0
                    disp('Python script executed successfully:');
                    disp(cmdout);
                else
                    disp('Error executing Python script:');
                    disp(cmdout);
                end

            elseif strcmp(app.OptionsDropDown_2.Value,"Load Sorting Results")

                if isfield(app.Mainapp.Data.Info,'CutStart')
                    msgbox("Warning: Start time of current dataset was cut. Please ensure, that spike sorting results are based on the same dataset");
                end
            
                if isfield(app.Mainapp.Data.Info,'CutEnd') 
                    msgbox("Warning: End time of current dataset was cut. Please ensure, that spike sorting results are based on the same dataset");
                end
            
                cd(app.Mainapp.executableFolder);
            
                app.Mainapp.LoadfromKilosortWindowWindow = Load_Spike_Sorting_Window(app.Mainapp);

            elseif strcmp(app.OptionsDropDown_2.Value,"Change Sorting Parameters")
                 
                if isempty(app.ParameterStructure)
                    [app.ParameterStructure,~] = Spike_Module_ShowSpikeSortingSettings(app.SorterDropDown.Value,app.ParameterStructure,"ParameterNOTPresent");
                end

                 app.SortingParameterWindow = SpikeInterface_SpikeSorting_Parameter(app.Mainapp,app);
                
            end
        end

        % Button pushed function: SetOutputFolderManuallyButton
        function SetOutputFolderManuallyButtonPushed(app, event)
            app.ManualSelection = 1;
            app.SpikeSortinBinPath = [];
            
            % Prompt the user to select a file
            filepath = uigetdir();
            
            % Check if the user selected a file or canceled the dialog
            if isequal(filepath, 0)
                disp('User canceled the file selection.');
                % Display the selected file's full path
                app.SpikeSortinBinPath = [];
                app.Label.FontColor = [1.00,0.00,0.00];
                
                app.Label.Text = "Manual selection of exported .bin file " + ...
                    "NOT Succesfull";
                return;
              
            else

                %% Check if SpikeInterface standard folder found
                [stringArray] = Utility_Extract_Contents_of_Folder(filepath);
                
                if isempty(app.SpikeInterfaceParameter)
                    TempMultipleRecordings = 0;
                else
                    TempMultipleRecordings = app.SpikeInterfaceParameter.MultipleRecordings;
                end

                if TempMultipleRecordings == 0
                    if sum(contains(stringArray,'.bin'))>0
                        a = find(contains(stringArray,'.bin')==1);
                        if length(a)>1 || isempty(a)
                            disp(strcat("More than one .bin file found in autosearched directory. Autosearched path can not be taken for sorting. Please select a .bin file manually!.",filepath))
                            app.CheckBox.Value = 0;
                            app.Label.FontColor = [1.00,0.00,0.00];
                            app.AutoSortingPathToBin = 0;
                            app.Label.Text = "Manual-detection of exported .bin file " + ...
                                "NOT Succesfull";
                            app.SpikeSortinBinPath = [];
                        else
                            disp(strcat(".bin found in autosearched directory ",filepath))
                            app.CheckBox.Value = 1;
                            app.Label.FontColor = [0.47,0.67,0.19];
                            app.AutoSortingPathToBin = 1;
                            app.Label.Text = "Manual-detection of exported .bin file " + ...
                                "Succesfull";
        
                            app.SpikeSortinBinPath = strcat(filepath,'\',stringArray(a));
                        end
                    else
                        disp(strcat("More than one .bin file found in autosearched directory. Autosearched path can not be taken for sorting. Please select a .bin file manually!.",filepath))
                        app.CheckBox.Value = 0;
                        app.Label.FontColor = [1.00,0.00,0.00];
                        app.AutoSortingPathToBin = 0;
                        app.Label.Text = "Manual-detection of exported .bin file " + ...
                            "NOT Succesfull.";
                        app.SpikeSortinBinPath = [];
                    end
                else
                        CuratedFoldercontents = [];
                        for i = 1:length(stringArray)
                            if contains(stringArray(i),".")
                        
                            elseif strcmp(stringArray(i),"")

                            else
                                CuratedFoldercontents = [CuratedFoldercontents;stringArray(i)];
                            end
                        end
                        
                        if length(CuratedFoldercontents)>0
                            app.CheckBox.Value = 1;
                            app.Label.FontColor = [0.47,0.67,0.19];
                            app.AutoSortingPathToBin = 1;
                            app.Label.Text = strcat("Manual-selection of ",num2str(length(CuratedFoldercontents))," folder " + ...
                            "Succesfull");
                            app.SpikeSortinBinPath = filepath;
                        else
                            app.CheckBox.Value = 0;
                            app.Label.FontColor = [1.00,0.00,0.00];
                            app.AutoSortingPathToBin = 0;
                            app.Label.Text = strcat("Manual-selection of ",num2str(length(CuratedFoldercontents))," folder " + ...
                            "NOT Succesfull");
                            app.SpikeSortinBinPath = [];
                        end
                        
                end
            end
        end

        % Button pushed function: SetOutputFolderManuallyButton_2
        function SetOutputFolderManuallyButton_2Pushed(app, event)
                if isfield(app.Mainapp.Data,'Spikes') && strcmp(app.Mainapp.Data.Info.SpikeType,'Internal')
                    SpikeText = strcat("Spike data already part of dataset with threshold ",num2str(app.Mainapp.Data.Info.SpikeDetectionNrStd),". If you want to load spike sorting data make sure the threshold is the same. Otherwise current spike dta is overwritten when loading sorting results.");
                else
                    SpikeText = strcat("No Existing Internal Spike Data was found");
                end
    
                folderPath = uigetdir();
                
                if isempty(folderPath)
                    return;
                end
    
                % Check if spike sorting was already saved
                % Check if the folder exists
                app.SpikeSortingPath = strcat(folderPath);
                
                if ~isfolder(app.SpikeSortingPath) % No sorting found
                    textboxx = strcat("Path: ",app.SpikeSortingPath," does not contain spike sorting results folder or path is not available anymore. Eihter start a new sorting or load a different folder");
                    SpikeExtractiontext = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";
                    SpikeExtractiontext2 = "'Filter Double Spike Indicies' is looking for spikes indicies part of the same waveform. 'Time Offset to Combine SpikeIndicies' is the length of that waveform and is added to each spike time. When the next spike is within the current spike time plus the time offset, they get combined to one spike.";
                        textboxx = [SpikeText;"";SpikeExtractiontext;"";SpikeExtractiontext2;"";textboxx];
                    app.TextArea.Value = textboxx;
                else
    
                    textboxx = strcat("Spike Sorting Data found in: ",app.SpikeSortingPath,". Select the 'Load Sorting Results' option and press 'RUN' to load spike sorting.");
    
                    if isfile(strcat(strcat(app.SpikeSortingPath,'\times_spikes.mat')))
                        load(strcat(strcat(app.SpikeSortingPath,'\times_spikes.mat')));
                        SortingType = "AllChannel";
                        textboxx = [textboxx;"";"Type: Sorted all channel together."];
                    else
                        if isfile(strcat(strcat(app.SpikeSortingPath,'\times_CH',num2str(1),'.mat')))
                            load(strcat(strcat(app.SpikeSortingPath,'\times_CH',num2str(1),'.mat')));
                            SortingType = "IndividualChannel";
                            textboxx = [textboxx;"";"Type: Sorted channel individually."];
                        else
                            textboxx = strcat("Path: ",app.SpikeSortingPath," does not contain spike sorting results folder or path is not available anymore. Eihter start a new sorting or load a different folder");
                            SpikeExtractiontext = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";
                            SpikeExtractiontext2 = "'Filter Double Spike Indicies' is looking for spikes indicies part of the same waveform. 'Time Offset to Combine SpikeIndicies' is the length of that waveform and is added to each spike time. When the next spike is within the current spike time plus the time offset, they get combined to one spike.";
                            textboxx = [SpikeText;"";SpikeExtractiontext;"";SpikeExtractiontext2;"";textboxx];
                            app.TextArea.Value = textboxx;
                            return;
                        end
                    end
    
                    if strcmp(SortingType,"AllChannel")
                        numclu = length(unique(cluster_class(:,1)));
        
                        textboxx = [textboxx;" ";strcat("Number of Cluster: ",num2str(numclu));""];
                        
                        % Initialize a string array to store the output
                        outputText = "";
                        
                        % Get all field names from the structure
                        fields = fieldnames(par);
        
                        % Loop through each field and concatenate the field name and value
                        for i = 1:numel(fields)
                            fieldName = fields{i};
                            fieldValue = par.(fieldName);
                            
                            % Convert the field value to a string if it is not already
                            if isnumeric(fieldValue)
                                fieldValue = num2str(fieldValue);
                            elseif ischar(fieldValue)
                                fieldValue = string(fieldValue);
                            end
                            
                            % Concatenate the field name and value
                            outputText = outputText + fieldName + ": " + fieldValue + newline;
                        end
                        
                        % Set the TextArea value in the app (assuming app.TextArea is the name of the TextArea)
                        textboxx = [textboxx;outputText];
                        SpikeExtractiontext = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";
                        SpikeExtractiontext2 = "'Filter Double Spike Indicies' is looking for spikes indicies part of the same waveform. 'Time Offset to Combine SpikeIndicies' is the length of that waveform and is added to each spike time. When the next spike is within the current spike time plus the time offset, they get combined to one spike.";
                        textboxx = [SpikeText;"";SpikeExtractiontext;"";SpikeExtractiontext2;"";textboxx;outputText];
        
                        app.TextArea.Value = textboxx;
                    else
                        if isfield(app.Mainapp.Data,'Spikes') && strcmp(app.Mainapp.Data.Info.SpikeType,'Internal')
                            SpikeText = strcat("Spike data already part of dataset with threshold ",num2str(app.Mainapp.Data.Info.SpikeDetectionNrStd),". If you want to load spike sorting data make sure the threshold is the same. Otherwise current spike dta is overwritten when loading sorting results.");
                        else
                            SpikeText = strcat("No Existing Internal Spike Data was found");
                        end
    
                        SpikeExtractiontext = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";
                        SpikeExtractiontext2 = "'Filter Double Spike Indicies' is looking for spikes indicies part of the same waveform. 'Time Offset to Combine SpikeIndicies' is the length of that waveform and is added to each spike time. When the next spike is within the current spike time plus the time offset, they get combined to one spike.";
                        textboxx = [SpikeText;"";textboxx;"";SpikeExtractiontext;"";SpikeExtractiontext2];
    
                        app.TextArea.Value = textboxx;
                    end
                end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SpikeDetectionWindowUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {814, 814};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpikeDetectionWindowUIFigure = uifigure('Visible', 'off');
            app.SpikeDetectionWindowUIFigure.Name = 'Spike Detection Window';
            app.SpikeDetectionWindowUIFigure.Icon = 'Logo.png';
            app.SpikeDetectionWindowUIFigure.CloseRequestFcn = createCallbackFcn(app, @SpikeDetectionWindowUIFigureCloseRequest, true);
            app.SpikeDetectionWindowUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.SpikeDetectionWindowUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.SpikeInterfaceSpikeSortingPanel = uipanel(app.RightPanel);
            app.SpikeInterfaceSpikeSortingPanel.Title = 'Spike Interface Spike Sorting';
            app.SorterDropDownLabel = uilabel(app.SpikeInterfaceSpikeSortingPanel);
            app.SorterDropDownLabel.Text = 'Sorter';
            app.SorterDropDown = uidropdown(app.SpikeInterfaceSpikeSortingPanel);
            app.SorterDropDown.Items = {'Mountainsort 5', 'SpykingCircus 2'};
            app.SorterDropDown.Value = 'Mountainsort 5';
            app.RUNButton_2 = uibutton(app.SpikeInterfaceSpikeSortingPanel, 'push');
            app.RUNButton_2.ButtonPushedFcn = createCallbackFcn(app, @RUNButton_2Pushed, true);
            app.RUNButton_2.Text = 'RUN';
            app.RunsviaPythonLabel = uilabel(app.SpikeInterfaceSpikeSortingPanel);
            app.RunsviaPythonLabel.Text = 'Runs via Python!';
            app.OptionsDropDown_2Label = uilabel(app.SpikeInterfaceSpikeSortingPanel);
            app.OptionsDropDown_2Label.Text = 'Options';
            app.OptionsDropDown_2 = uidropdown(app.SpikeInterfaceSpikeSortingPanel);
            app.OptionsDropDown_2.Items = {'Start New Spike Sorting', 'Change Sorting Parameters', 'Load Sorting Results'};
            app.OptionsDropDown_2.Value = 'Start New Spike Sorting';
            app.SetOutputFolderManuallyButton = uibutton(app.SpikeInterfaceSpikeSortingPanel, 'push');
            app.SetOutputFolderManuallyButton.ButtonPushedFcn = createCallbackFcn(app, @SetOutputFolderManuallyButtonPushed, true);
            app.SetOutputFolderManuallyButton.Text = {'Manually Select Folder'; 'with exported .bin file'};
            app.NotrecommendedLabel = uilabel(app.SpikeInterfaceSpikeSortingPanel);
            app.NotrecommendedLabel.Text = 'Not recommended!';
            app.Label = uilabel(app.SpikeInterfaceSpikeSortingPanel);
            app.CheckBox = uicheckbox(app.SpikeInterfaceSpikeSortingPanel);
            app.CheckBox.Enable = 'off';
            app.CheckBox.Text = '';
            app.SpikeDetectionandSortingLabel = uilabel(app.RightPanel);
            app.SpikeDetectionandSortingLabel.Text = 'Spike Detection and Sorting';
            app.NeuromodLabel = uilabel(app.RightPanel);
            app.NeuromodLabel.Text = 'Neuromod';
            app.CloseButton = uibutton(app.RightPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.TextArea = uitextarea(app.RightPanel);
            app.InformationLabel = uilabel(app.RightPanel);
            app.InformationLabel.Text = 'Information';
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = 'Logo.png';
            app.InternalSpikeDetectionSettingsPanel = uipanel(app.RightPanel);
            app.InternalSpikeDetectionSettingsPanel.Title = 'Internal Spike Detection Settings';
            app.DetectionMethodLabel = uilabel(app.InternalSpikeDetectionSettingsPanel);
            app.DetectionMethodLabel.Text = {'Detection '; 'Method'};
            app.DetectionMethodDropDown = uidropdown(app.InternalSpikeDetectionSettingsPanel);
            app.DetectionMethodDropDown.Items = {'Quiroga Method', 'Threshold: Mean - Std', 'Threshold: Median - Std'};
            app.DetectionMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @DetectionMethodDropDownValueChanged, true);
            app.DetectionMethodDropDown.Value = 'Quiroga Method';
            app.ThresholdEditFieldLabel = uilabel(app.InternalSpikeDetectionSettingsPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.InternalSpikeDetectionSettingsPanel, 'text');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdEditField.Value = '4';
            app.MeanandStdDropDownLabel = uilabel(app.InternalSpikeDetectionSettingsPanel);
            app.MeanandStdDropDownLabel.Text = 'Mean and Std';
            app.MeanDropDown = uidropdown(app.InternalSpikeDetectionSettingsPanel);
            app.MeanDropDown.Items = {'All Channel', 'Individual Ch.'};
            app.MeanDropDown.Value = 'All Channel';
            app.FilterVerticalSpikeArtefactsCheckBox = uicheckbox(app.InternalSpikeDetectionSettingsPanel);
            app.FilterVerticalSpikeArtefactsCheckBox.ValueChangedFcn = createCallbackFcn(app, @FilterVerticalSpikeArtefactsCheckBoxValueChanged, true);
            app.FilterVerticalSpikeArtefactsCheckBox.Text = {'      Filter Vertical '; '      Spike Artefacts'};
            app.VerticalSpikeOffsetToleranceSamplesEditFieldLabel = uilabel(app.InternalSpikeDetectionSettingsPanel);
            app.VerticalSpikeOffsetToleranceSamplesEditFieldLabel.Text = {'Vertical Spike Offset'; 'Tolerance [Samples]'};
            app.VerticalSpikeOffsetToleranceSamplesEditField = uieditfield(app.InternalSpikeDetectionSettingsPanel, 'text');
            app.VerticalSpikeOffsetToleranceSamplesEditField.ValueChangedFcn = createCallbackFcn(app, @VerticalSpikeOffsetToleranceSamplesEditFieldValueChanged, true);
            app.VerticalSpikeOffsetToleranceSamplesEditField.Value = '3';
            app.MinDepthofArtefactmEditFieldLabel = uilabel(app.InternalSpikeDetectionSettingsPanel);
            app.MinDepthofArtefactmEditFieldLabel.Text = {'Min. Depth of Artefact'; '[µm]'};
            app.MinDepthofArtefactmEditField = uieditfield(app.InternalSpikeDetectionSettingsPanel, 'text');
            app.MinDepthofArtefactmEditField.ValueChangedFcn = createCallbackFcn(app, @MinDepthofArtefactmEditFieldValueChanged, true);
            app.MinDepthofArtefactmEditField.Value = '200';
            app.FilterDoubleSpikeIndiciesCheckBox = uicheckbox(app.InternalSpikeDetectionSettingsPanel);
            app.FilterDoubleSpikeIndiciesCheckBox.Text = {'     Filter Spikes in '; '     Same Waveform'};
            app.FilterDoubleSpikeIndiciesCheckBox.Value = true;
            app.TimeSpantoCombineSpikeIndiciesEditFieldLabel = uilabel(app.InternalSpikeDetectionSettingsPanel);
            app.TimeSpantoCombineSpikeIndiciesEditFieldLabel.Text = {'Time Span to Combine'; 'Spike Indicies'};
            app.TimeOffsettoCombineSpikeIndiciessEditField = uieditfield(app.InternalSpikeDetectionSettingsPanel, 'text');
            app.TimeOffsettoCombineSpikeIndiciessEditField.ValueChangedFcn = createCallbackFcn(app, @TimeOffsettoCombineSpikeIndiciessEditFieldValueChanged, true);
            app.TimeOffsettoCombineSpikeIndiciessEditField.Value = '0.001';
            app.StartSpikeDetectionButton = uibutton(app.InternalSpikeDetectionSettingsPanel, 'push');
            app.StartSpikeDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @StartSpikeDetectionButtonPushed, true);
            app.StartSpikeDetectionButton.Text = {'Start Spike '; 'Detection'};
            app.Wave_clus3SpikeSortingPanel = uipanel(app.RightPanel);
            app.Wave_clus3SpikeSortingPanel.Title = 'Wave_clus 3 Spike Sorting';
            app.OptionsDropDownLabel = uilabel(app.Wave_clus3SpikeSortingPanel);
            app.OptionsDropDownLabel.Text = 'Options';
            app.OptionsDropDown = uidropdown(app.Wave_clus3SpikeSortingPanel);
            app.OptionsDropDown.Items = {'Start New Spike Sorting', 'Change Sorting Parameters', 'Load Sorting Results'};
            app.OptionsDropDown.ValueChangedFcn = createCallbackFcn(app, @OptionsDropDownValueChanged, true);
            app.OptionsDropDown.Value = 'Start New Spike Sorting';
            app.SortforIndividualChannelCheckBox = uicheckbox(app.Wave_clus3SpikeSortingPanel);
            app.SortforIndividualChannelCheckBox.ValueChangedFcn = createCallbackFcn(app, @SortforIndividualChannelCheckBoxValueChanged, true);
            app.SortforIndividualChannelCheckBox.Text = '    Sort for Individual Channel';
            app.SortallChannelTogetherCheckBox = uicheckbox(app.Wave_clus3SpikeSortingPanel);
            app.SortallChannelTogetherCheckBox.ValueChangedFcn = createCallbackFcn(app, @SortallChannelTogetherCheckBoxValueChanged, true);
            app.SortallChannelTogetherCheckBox.Text = '    Sort all Channel Together';
            app.SortallChannelTogetherCheckBox.Value = true;
            app.RUNButton = uibutton(app.Wave_clus3SpikeSortingPanel, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.RunsviaMatlabLabel = uilabel(app.Wave_clus3SpikeSortingPanel);
            app.RunsviaMatlabLabel.Text = 'Runs via Matlab!';
            app.SetOutputFolderManuallyButton_2 = uibutton(app.Wave_clus3SpikeSortingPanel, 'push');
            app.SetOutputFolderManuallyButton_2.ButtonPushedFcn = createCallbackFcn(app, @SetOutputFolderManuallyButton_2Pushed, true);
            app.SetOutputFolderManuallyButton_2.Text = {'Set Output Folder'; 'Manually'};
            app.NotrecommendedLabel_2 = uilabel(app.Wave_clus3SpikeSortingPanel);
            app.NotrecommendedLabel_2.Text = 'Not recommended!';
            app.TextArea_2 = uitextarea(app.RightPanel);
            app.TextArea_2.Value = {'This toolbox supports the analysis of spike clustering results directly from the Kilosort GUI (via ''Save for Spike Sorting'' and ''Load Spike Sorting'' options in the main window). This requires opening the Kilosort GUI and load your dataset along with probe information seperately to conduct the sorting. However, you can also use the provided option to do your clustering with SpikeInterface (and some sorters available there) fully automatically. Export your dataset for SpikeInterface (via ''Save for Spike Sorting''), click on run in this window and wait till the python script finished, then load the results into this GUI. Everything else is taken care of here. See the manual for all necessary python packages and further information.'; 'To conduct spike sorting solely in Matlab you can use Wave_clus 3. '; 'Output folder are created automatically. It is recommended to not change the location or name of the output folders. Standard folder structures allow for autodetection of results, single click loading and a smooth autorun function looping over several recordings.'; ''};
            app.InformationLabel_2 = uilabel(app.RightPanel);
            app.InformationLabel_2.Text = 'Information';
            % Show the figure after all components are created
            app.SpikeDetectionWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Spike_Detection_and_Sorting_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SpikeDetectionWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.SpikeDetectionWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpikeDetectionWindowUIFigure)
        end
    end
end