classdef Load_Spike_Sorting_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LoadSpikeSortingResultsUIFigure  matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        RightPanel                    matlab.ui.container.Panel
        LoadingOptionsPanel           matlab.ui.container.Panel
        FoldercontainingaSpikeInterfaceandorKilosortfolderLabel  matlab.ui.control.Label
        LoadButton                    matlab.ui.control.Button
        SpikeSorterDropDown           matlab.ui.control.DropDown
        SpikeSorterDropDownLabel      matlab.ui.control.Label
        SelectAmplitudeScalingManuallyButton  matlab.ui.control.Button
        EmptyfornonLabel              matlab.ui.control.Label
        AmplitudeScalingFactorEditField  matlab.ui.control.EditField
        AmplitudeScalingFactorEditFieldLabel  matlab.ui.control.Label
        SelectKilosortFolderManuallyButton  matlab.ui.control.Button
        Image                         matlab.ui.control.Image
        InformationTextArea           matlab.ui.control.TextArea
        InformationTextAreaLabel      matlab.ui.control.Label
        CloseButton                   matlab.ui.control.Button
        NeuromodLabel                 matlab.ui.control.Label
        LoadSpikeSortingResultsLabel  matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Mainapp=[]; % Description
        Path = [];
        CurrentSorter = [];
        ManualSelection = 0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            movegui(app.LoadSpikeSortingResultsUIFigure, 'center');
            app.Mainapp = Mainapp;

            SoterFolders(1) = strcat(app.Mainapp.Data.Info.Data_Path,"\Kilosort\kilosort4");
            SoterFolders(2) = strcat(app.Mainapp.Data.Info.Data_Path,"\Kilosort\kilosort3");
            SoterFolders(3) = strcat(app.Mainapp.Data.Info.Data_Path,"\SpikeInterface\SpikeInterface_Sorting_Phy_Results\Mountainsort 5");
            SoterFolders(4) = strcat(app.Mainapp.Data.Info.Data_Path,"\SpikeInterface\SpikeInterface_Sorting_Phy_Results\SpykingCircus 2");
            SoterFolders(5) = strcat(app.Mainapp.Data.Info.Data_Path,"\Wave_Clus");
            SoterFolders(6) = strcat(app.Mainapp.Data.Info.Data_Path,"\SpikeInterface\SpikeInterface_Sorting_Phy_Results\Kilosort 4");
            
            app.Path = app.Mainapp.Data.Info.Data_Path;

            [~,app.CurrentSorter,app.AmplitudeScalingFactorEditField.Value,InfoText] = Spike_Module_Determine_Sorters_Available(app.Mainapp.Data,SoterFolders,app.ManualSelection,0);

            if strcmp(app.CurrentSorter,"Mountainsort5") 
                app.SpikeSorterDropDown.Value = app.SpikeSorterDropDown.Items{3};
            elseif strcmp(app.CurrentSorter,"SpykingCircus2")
                app.SpikeSorterDropDown.Value = app.SpikeSorterDropDown.Items{4};
            elseif strcmp(app.CurrentSorter,"Kilosort3") || strcmp(app.CurrentSorter,"Kilosort4")
                app.SpikeSorterDropDown.Value = app.SpikeSorterDropDown.Items{2};
            elseif strcmp(app.CurrentSorter,"SpikeInterface Kilosort") 
                app.SpikeSorterDropDown.Value = app.SpikeSorterDropDown.Items{1};
            elseif strcmp(app.CurrentSorter,"WaveClus")
                app.SpikeSorterDropDown.Value = app.SpikeSorterDropDown.Items{5};
            end

            app.InformationTextArea.Value = InfoText;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            if isempty(app.Path)
                msgbox("No Path selected. Please first select a folder.");
                return;
            end

            if ~isfolder(app.Path)
                msgbox("Selected Path does not exist. Please select an exisitng folder.");
                return;
            end

            if isempty(app.CurrentSorter)
                msgbox("No valid sorting files found in selected folder.");
                return;
            end
            
            if isempty(app.AmplitudeScalingFactorEditField.Value)
                KilosortScalingFactor = [];
            else
                KilosortScalingFactor = str2double(app.AmplitudeScalingFactorEditField.Value);
            end

            if strcmp(app.CurrentSorter,"Kilosort4") 
                LoadFolder = strcat(app.Path,"\Kilosort\kilosort4");
            elseif strcmp(app.CurrentSorter,"Kilosort3")
                LoadFolder = strcat(app.Path,"\Kilosort\kilosort3");
            elseif strcmp(app.CurrentSorter,"Mountainsort5")
                LoadFolder = strcat(app.Path,"\SpikeInterface\SpikeInterface_Sorting_Phy_Results\Mountainsort 5");
            elseif strcmp(app.CurrentSorter,"SpykingCircus2")
                LoadFolder = strcat(app.Path,"\SpikeInterface\SpikeInterface_Sorting_Phy_Results\SpykingCircus 2");
            elseif strcmp(app.CurrentSorter,"WaveClus")
                LoadFolder = strcat(app.Path,"\Wave_Clus");
            elseif strcmp(app.CurrentSorter,"SpikeInterface Kilosort")
                LoadFolder = strcat(app.Path,"\SpikeInterface\SpikeInterface_Sorting_Phy_Results\Kilosort 4");
            end
                        
            if strcmp(app.SpikeSorterDropDown.Value,"Mountainsort 5") || strcmp(app.SpikeSorterDropDown.Value,"SpykingCircus 2") || strcmp(app.SpikeSorterDropDown.Value,"Kilosort SpikeInterface")
                [app.Mainapp.Data,SaveFilter] = Spike_Module_Load_SpikeInterface_Sorter(app.Mainapp.Data,LoadFolder,app.CurrentSorter);
            end

            if strcmp(app.SpikeSorterDropDown.Value,"Kilosort external GUI")
                % Function to load all relevant npy and .mat files Kilosort outputs
                [app.Mainapp.Data,SaveFilter] = Spike_Module_Load_Kilosort_Data(app.Mainapp.Data,"No",LoadFolder,KilosortScalingFactor);
            end

            if strcmp(app.SpikeSorterDropDown.Value,"WaveClus 3")
                %if isfield(app.Mainapp.Data,'Spikes') && strcmp(app.Mainapp.Data.Info.SpikeType,'Internal')
                    [app.Mainapp.Data] = Spike_Module_Internal_Spike_Sorting(app.Mainapp.Data,LoadFolder,"Loading",[],app.CurrentSorter);
                    SaveFilter = "No";
                %else
                    %msgbox("To load WaveClus sorting, you first have to conduct spike detection in the upper part of the 'Spike Detection and Sorting' window! Skipping.")
                    %return;
                %end
            end

            app.Mainapp.UIAxes.NextPlot = "replace"; 
            plot(app.Mainapp.UIAxes,0,0);
            app.Mainapp.UIAxes.NextPlot = "add"; 
            app.Mainapp.UIAxes_2.NextPlot = "replace"; 
            plot(app.Mainapp.UIAxes_2,0,0);
            app.Mainapp.UIAxes_2.NextPlot = "add"; 

            %Take care of potentially changed backgroundcolor
            app.Mainapp.UIAxes.Color = app.Mainapp.PlotAppearance.MainWindow.Data.Color.MainBackground;
            app.Mainapp.UIAxes_2.Color = app.Mainapp.PlotAppearance.MainWindow.Data.Color.TimeBackground;
            app.Mainapp.UIAxes.FontSize = app.Mainapp.PlotAppearance.MainWindow.Data.MainFontSize;
            app.Mainapp.UIAxes_2.FontSize = app.Mainapp.PlotAppearance.MainWindow.Data.TimeFontSize;
            
            if ~isfield(app.Mainapp.Data,'Events')
                if strcmp(app.Mainapp.PlotEvents,"Events")
                    app.Mainapp.PlotEvents = "No";
                end
                Placeholder = {};
                app.Mainapp.DropDown_2.Items = Placeholder;
                app.Mainapp.DropDown_2.Items{1} = 'Non';
            else
                Placeholder = {};
                app.Mainapp.DropDown_2.Items = Placeholder;
                app.Mainapp.DropDown_2.Items{1} = 'Non';
                app.Mainapp.DropDown_2.Items{2} = 'Events';
                if strcmp(app.Mainapp.PlotEvents,"Events")
                    app.Mainapp.DropDown_2.Value = 'Events';
                end
            end
        
            if ~isfield(app.Mainapp.Data,'Spikes')
                % if strcmp(app.Mainapp.Plotspikes,"Spikes")
                %     app.Mainapp.Plotspikes = "No";
                % end
            else
                app.Mainapp.DropDown_2.Items = Placeholder;
                app.Mainapp.DropDown_2.Items{1} = 'Non';
                if isfield(app.Mainapp.Data,'Events')
                    app.Mainapp.DropDown_2.Items{2} = 'Events';
                    app.Mainapp.DropDown_2.Items{3} = 'Spikes';
                else
                    app.Mainapp.DropDown_2.Items{2} = 'Spikes';
                end
                
                if strcmp(app.Mainapp.PlotEvents,"Events")
                    app.Mainapp.DropDown_2.Value = 'Events';
                else
                    app.Mainapp.DropDown_2.Value = 'Non';
                end
            end

            if strcmp(app.Mainapp.Plotspikes,"Spikes")
                SpikeHandles = findobj(app.Mainapp.UIAxes, 'Type', 'line', 'Tag', 'Spikes');
                if ~isempty(SpikeHandles)
                    delete(SpikeHandles(:));
                end
                app.Mainapp.Plotspikes = "No";
            end

            Utility_Show_Info_Loaded_Data(app.Mainapp);
            
            if strcmp(SaveFilter,"Yes")
                [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],[]);
            end

            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","SpikeExtraction",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);

            delete(app);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.Mainapp.LoadfromKilosortWindowWindow = [];
            delete(app);
        end

        % Button pushed function: SelectKilosortFolderManuallyButton
        function SelectKilosortFolderManuallyButtonPushed(app, event)
            
            app.ManualSelection = 1;
            
            SorterSelected = 0;
            % Prompt user to select a folder
            folderPath = uigetdir('Select a folder');
            
            % Check if user cancels the dialog
            if folderPath == 0
                disp('Operation canceled by user');
                return;
            end

            SoterFolders(1) = strcat(folderPath,"\Kilosort\kilosort4");
            SoterFolders(2) = strcat(folderPath,"\Kilosort\kilosort3");
            SoterFolders(3) = strcat(folderPath,"\SpikeInterface\SpikeInterface_Sorting_Phy_Results\Mountainsort 5");
            SoterFolders(4) = strcat(folderPath,"\SpikeInterface\SpikeInterface_Sorting_Phy_Results\SpykingCircus 2");
            SoterFolders(5) = strcat(folderPath,"\Wave_Clus");
            SoterFolders(6) = strcat(folderPath,"\SpikeInterface\SpikeInterface_Sorting_Phy_Results\Kilosort 4");
            
            app.Path = folderPath;

            [~,app.CurrentSorter,app.AmplitudeScalingFactorEditField.Value,InfoText] = Spike_Module_Determine_Sorters_Available(app.Mainapp.Data,SoterFolders,0,SorterSelected);
            
            
            if strcmp(app.CurrentSorter,"Mountainsort 5") 
                app.SpikeSorterDropDown.Value = app.SpikeSorterDropDown.Items{3};
            elseif strcmp(app.CurrentSorter,"SpykingCircus 2")
                app.SpikeSorterDropDown.Value = app.SpikeSorterDropDown.Items{4};
            elseif strcmp(app.CurrentSorter,"Kilosort3") || strcmp(app.CurrentSorter,"Kilosort4")
                app.SpikeSorterDropDown.Value = app.SpikeSorterDropDown.Items{2};
            elseif strcmp(app.CurrentSorter,"SpikeInterface Kilosort") 
                app.SpikeSorterDropDown.Value = app.SpikeSorterDropDown.Items{1};
            elseif strcmp(app.CurrentSorter,"WaveClus")
                app.SpikeSorterDropDown.Value = app.SpikeSorterDropDown.Items{5};
            end

            app.InformationTextArea.Value = InfoText;
        end

        % Button pushed function: SelectAmplitudeScalingManuallyButton
        function SelectAmplitudeScalingManuallyButtonPushed(app, event)
            % Prompt user to select a folder
            filePath = uigetfile('Select a folder');
            
            % Check if user cancels the dialog
            if filePath == 0
                disp('Operation canceled by user');
                return;
            end
            
            %% Check if selected mat file contains correct variable
            variableName = 'scalingFactor';  % Variable you want to load
            
            % Get the list of variables in the file
            variablesInFile = who('-file', filePath);
            
            % Check if the desired variable exists
            if ismember(variableName, variablesInFile)
                load(filePath, variableName);  % Load only the specific variable
            else
                msgbox(strcat("Variable ", variableName," does not exist in the manually selected file ",filePath));
                return;  % Exit if the variable does not exist
            end
            
            load(filePath,'scalingFactor');
            
            scalindicie = find(contains(app.InformationTextArea.Value, 'Scaling'));
            % cut out previous scaling info shown
            StringtoShow = app.InformationTextArea.Value(1:scalindicie(1)-1);
            % add new scaling info 
            StringtoShow = [StringtoShow; "Scaling factor to convert int format of the kilosort output to mV found in manually selected a .mat file."];
            
            
            app.AmplitudeScalingFactorEditField.Value = num2str(scalingFactor);
            app.Mainapp.Data.Info.KilosortScalingFactor = scalingFactor;

            app.InformationTextArea.Value = StringtoShow;
        end

        % Close request function: LoadSpikeSortingResultsUIFigure
        function LoadSpikeSortingResultsUIFigureCloseRequest(app, event)
            app.Mainapp.LoadfromKilosortWindowWindow = [];
            delete(app);
        end

        % Value changed function: SpikeSorterDropDown
        function SpikeSorterDropDownValueChanged(app, event)
           
            if app.ManualSelection == 0
                if strcmp(app.SpikeSorterDropDown.Value,"Kilosort external GUI")
                    SoterFolders(1) = strcat(app.Path,"\Kilosort\kilosort4");
                    SoterFolders(2) = strcat(app.Path,"\Kilosort\kilosort3");
                    SoterFolders(3) = "";
                    SoterFolders(4) = "";          
                    SoterFolders(5) = "";  
                    SoterFolders(6) = ""; 
                elseif strcmp(app.SpikeSorterDropDown.Value,"Kilosort SpikeInterface")
                    SoterFolders(1) = "";
                    SoterFolders(2) = "";
                    SoterFolders(3) = "";
                    SoterFolders(4) = "";          
                    SoterFolders(5) = "";  
                    SoterFolders(6) = strcat(app.Path,"\SpikeInterface\SpikeInterface_Sorting_Phy_Results\Kilosort 4");
                elseif strcmp(app.SpikeSorterDropDown.Value,"Mountainsort 5")
                    SoterFolders(1) = "";
                    SoterFolders(2) = "";
                    SoterFolders(3) = strcat(app.Path,"\SpikeInterface\SpikeInterface_Sorting_Phy_Results\Mountainsort 5");
                    SoterFolders(4) = "";
                    SoterFolders(5) = ""; 
                    SoterFolders(6) = "";
                elseif strcmp(app.SpikeSorterDropDown.Value,"SpykingCircus 2")
                    SoterFolders(1) = "";
                    SoterFolders(2) = "";
                    SoterFolders(3) = "";
                    SoterFolders(4) = strcat(app.Path,"\SpikeInterface\SpikeInterface_Sorting_Phy_Results\SpykingCircus 2");
                    SoterFolders(5) = ""; 
                    SoterFolders(6) = "";
                elseif strcmp(app.SpikeSorterDropDown.Value,"WaveClus 3")
                    SoterFolders(1) = "";
                    SoterFolders(2) = "";
                    SoterFolders(3) = "";
                    SoterFolders(4) = "";
                    SoterFolders(5) = strcat(app.Path,"\Wave_Clus"); 
                    SoterFolders(6) = "";
                end

                SorterSelected = 0;

            else

                SorterSelected = 0;

                if strcmp(app.SpikeSorterDropDown.Value,"Kilosort external GUI")
                    SoterFolders(1) = strcat(app.Path,"\Kilosort\kilosort4");
                    SoterFolders(2) = strcat(app.Path,"\Kilosort\kilosort3");
                    SoterFolders(3) = "";
                    SoterFolders(4) = "";          
                    SoterFolders(5) = "";  
                    SoterFolders(6) = ""; 
                elseif strcmp(app.SpikeSorterDropDown.Value,"Kilosort SpikeInterface")
                    SoterFolders(1) = "";
                    SoterFolders(2) = "";
                    SoterFolders(3) = "";
                    SoterFolders(4) = "";          
                    SoterFolders(5) = "";  
                    SoterFolders(6) = strcat(app.Path,"\SpikeInterface\SpikeInterface_Sorting_Phy_Results\Kilosort 4");
                elseif strcmp(app.SpikeSorterDropDown.Value,"Mountainsort 5")
                    SoterFolders(1) = "";
                    SoterFolders(2) = "";
                    SoterFolders(3) = strcat(app.Path,"\SpikeInterface\SpikeInterface_Sorting_Phy_Results\Mountainsort 5");
                    SoterFolders(4) = "";
                    SoterFolders(5) = ""; 
                    SoterFolders(6) = "";
                elseif strcmp(app.SpikeSorterDropDown.Value,"SpykingCircus 2")
                    SoterFolders(1) = "";
                    SoterFolders(2) = "";
                    SoterFolders(3) = "";
                    SoterFolders(4) = strcat(app.Path,"\SpikeInterface\SpikeInterface_Sorting_Phy_Results\SpykingCircus 2");
                    SoterFolders(5) = ""; 
                    SoterFolders(6) = "";
                elseif strcmp(app.SpikeSorterDropDown.Value,"WaveClus 3")
                    SoterFolders(1) = "";
                    SoterFolders(2) = "";
                    SoterFolders(3) = "";
                    SoterFolders(4) = "";
                    SoterFolders(5) = strcat(app.Path,"\Wave_Clus"); 
                    SoterFolders(6) = "";
                end
            end


            [~,app.CurrentSorter,app.AmplitudeScalingFactorEditField.Value,InfoText] = Spike_Module_Determine_Sorters_Available(app.Mainapp.Data,SoterFolders,0,SorterSelected);

            if ~strcmp(app.SpikeSorterDropDown.Value,"Kilosort external GUI")
                app.AmplitudeScalingFactorEditField.Value = "";
            end
           
            app.InformationTextArea.Value = InfoText;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.LoadSpikeSortingResultsUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {478, 478};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LoadSpikeSortingResultsUIFigure = uifigure('Visible', 'off');
            app.LoadSpikeSortingResultsUIFigure.Name = 'Load Spike Sorting Results';
            app.LoadSpikeSortingResultsUIFigure.Icon = 'Logo.png';
            app.LoadSpikeSortingResultsUIFigure.CloseRequestFcn = createCallbackFcn(app, @LoadSpikeSortingResultsUIFigureCloseRequest, true);
            app.LoadSpikeSortingResultsUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.LoadSpikeSortingResultsUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.LoadSpikeSortingResultsLabel = uilabel(app.RightPanel);
            app.LoadSpikeSortingResultsLabel.Text = 'Load Spike Sorting Results';
            app.NeuromodLabel = uilabel(app.RightPanel);
            app.NeuromodLabel.Text = 'Neuromod';
            app.CloseButton = uibutton(app.RightPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.InformationTextAreaLabel = uilabel(app.RightPanel);
            app.InformationTextAreaLabel.Text = 'Information';
            app.InformationTextArea = uitextarea(app.RightPanel);
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = 'Logo.png';
            app.LoadingOptionsPanel = uipanel(app.RightPanel);
            app.LoadingOptionsPanel.Title = 'Loading Options';
            app.SelectKilosortFolderManuallyButton = uibutton(app.LoadingOptionsPanel, 'push');
            app.SelectKilosortFolderManuallyButton.ButtonPushedFcn = createCallbackFcn(app, @SelectKilosortFolderManuallyButtonPushed, true);
            app.SelectKilosortFolderManuallyButton.Text = {'Manually Select'; 'Recording Folder'};
            app.AmplitudeScalingFactorEditFieldLabel = uilabel(app.LoadingOptionsPanel);
            app.AmplitudeScalingFactorEditFieldLabel.Text = {'Amplitude'; 'Scaling Factor'};
            app.AmplitudeScalingFactorEditField = uieditfield(app.LoadingOptionsPanel, 'text');
            app.EmptyfornonLabel = uilabel(app.LoadingOptionsPanel);
            app.EmptyfornonLabel.Text = '(Empty for non)';
            app.SelectAmplitudeScalingManuallyButton = uibutton(app.LoadingOptionsPanel, 'push');
            app.SelectAmplitudeScalingManuallyButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAmplitudeScalingManuallyButtonPushed, true);
            app.SelectAmplitudeScalingManuallyButton.Text = {'Select Amplitude'; 'Scaling Manually'};
            app.SpikeSorterDropDownLabel = uilabel(app.LoadingOptionsPanel);
            app.SpikeSorterDropDownLabel.Text = 'SpikeSorter';
            app.SpikeSorterDropDown = uidropdown(app.LoadingOptionsPanel);
            app.SpikeSorterDropDown.Items = {'Kilosort SpikeInterface', 'Kilosort external GUI', 'Mountainsort 5', 'SpykingCircus 2', 'WaveClus 3'};
            app.SpikeSorterDropDown.ValueChangedFcn = createCallbackFcn(app, @SpikeSorterDropDownValueChanged, true);
            app.SpikeSorterDropDown.Value = 'Kilosort SpikeInterface';
            app.LoadButton = uibutton(app.LoadingOptionsPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.FoldercontainingaSpikeInterfaceandorKilosortfolderLabel = uilabel(app.LoadingOptionsPanel);
            app.FoldercontainingaSpikeInterfaceandorKilosortfolderLabel.Text = {'Folder containing a ''SpikeInterface'''; '''Kilosort'' and/or ''Wave_Clus'' folder'};
            % Show the figure after all components are created
            app.LoadSpikeSortingResultsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Load_Spike_Sorting_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.LoadSpikeSortingResultsUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.LoadSpikeSortingResultsUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LoadSpikeSortingResultsUIFigure)
        end
    end
end