classdef Kilosort_Loadfile_Format_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LoadSpikeSortingResultsUIFigure  matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        RightPanel                    matlab.ui.container.Panel
        LoadingOptionsPanel           matlab.ui.container.Panel
        LoadButton                    matlab.ui.control.Button
        SpikeSorterDropDown           matlab.ui.control.DropDown
        SpikeSorterDropDownLabel      matlab.ui.control.Label
        SelectAmplitudeScalingManuallyButton  matlab.ui.control.Button
        EmptyfornonLabel              matlab.ui.control.Label
        AmplitudeScalingFactorEditField  matlab.ui.control.EditField
        AmplitudeScalingFactorEditFieldLabel  matlab.ui.control.Label
        SelectKilosortFolderManuallyButton  matlab.ui.control.Button
        Image                         matlab.ui.control.Image
        InformationTextArea           matlab.ui.control.TextArea
        InformationTextAreaLabel      matlab.ui.control.Label
        CloseButton                   matlab.ui.control.Button
        NeuromodLabel                 matlab.ui.control.Label
        LoadSpikeSortingResultsLabel  matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Mainapp=[]; % Description
        Path = [];
        CurrentSorter = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            movegui(app.LoadSpikeSortingResultsUIFigure, 'center');
            app.Mainapp = Mainapp;

            Kilosort4FoldertoSeach = strcat(app.Mainapp.Data.Info.Data_Path,'\Kilosort\kilosort4');
            Kilosort3FoldertoSeach = strcat(app.Mainapp.Data.Info.Data_Path,'\Kilosort\kilosort3');
            
            MS5SpikeInterfaceFoldertoSeach = strcat(app.Mainapp.Data.Info.Data_Path,'\SpikeInterface\SpikeInterface_Sorting_Phy_Results\mountainsort5');
            SC2SpikeInterfaceFoldertoSeach = strcat(app.Mainapp.Data.Info.Data_Path,'\SpikeInterface\SpikeInterface_Sorting_Phy_Results\spykingcircus2');

            StringtoShow = [];

            if isfolder(Kilosort4FoldertoSeach) || isfolder(Kilosort3FoldertoSeach)
                if isfolder(Kilosort4FoldertoSeach)
                    [stringArray] = Utility_Extract_Contents_of_Folder(Kilosort4FoldertoSeach);
                    StringtoShow = strcat("Auto-searched Path (Original Recording Folder): ", Kilosort4FoldertoSeach);
                    StringtoShow = [StringtoShow;"";"Searching for sorting output results (.npy files) that are used to start phy (either with native Kilosort ouput files or with Spikeinterface using the 'export to Phy' functionality). Scaling factor only needed when loading Kilosort output files.";"";"Note: When loading SpikeInterface results, a 'SpikePositions'.mat file is needed holding the spike positions in um. When Spikeinterface sorting is done in this GUI, it will be created automatically."];
                    app.Path = Kilosort4FoldertoSeach;
                    app.CurrentSorter = "Kilosort4";
                else
                    [stringArray] = Utility_Extract_Contents_of_Folder(Kilosort3FoldertoSeach);
                    StringtoShow = strcat("Auto-searched Path (Original Recording Folder): ", Kilosort3FoldertoSeach);
                    StringtoShow = [StringtoShow;"";"Searching for sorting output results (.npy files) that are used to start phy (either with native Kilosort ouput files or with Spikeinterface using the 'export to Phy' functionality). Scaling factor only needed when loading Kilosort output files.";"";"Note: When loading SpikeInterface results, a 'SpikePositions'.mat file is needed holding the spike positions in um. When Spikeinterface sorting is done in this GUI, it will be created automatically."];
                    app.Path = Kilosort3FoldertoSeach;
                    app.CurrentSorter = "Kilosort3";
                end
            else
                if isfolder(MS5SpikeInterfaceFoldertoSeach) || isfolder(SC2SpikeInterfaceFoldertoSeach)
                    if isfolder(MS5SpikeInterfaceFoldertoSeach)
                        [stringArray] = Utility_Extract_Contents_of_Folder(MS5SpikeInterfaceFoldertoSeach);
                        StringtoShow = strcat("Auto-searched Path (Original Recording Folder): ", MS5SpikeInterfaceFoldertoSeach);
                        StringtoShow = [StringtoShow;"";"Searching for sorting output results (.npy files) that are used to start phy (either with native Kilosort ouput files or with Spikeinterface using the 'export to Phy' functionality). Scaling factor only needed when loading Kilosort output files.";"";"Note: When loading SpikeInterface results, a 'SpikePositions'.mat file is needed holding the spike positions in um. When Spikeinterface sorting is done in this GUI, it will be created automatically."];
                        app.Path = MS5SpikeInterfaceFoldertoSeach;
                        app.CurrentSorter = "Mountainsort5";
                    elseif isfolder(SC2SpikeInterfaceFoldertoSeach)
                        [stringArray] = Utility_Extract_Contents_of_Folder(SC2SpikeInterfaceFoldertoSeach);
                        StringtoShow = strcat("Auto-searched Path (Original Recording Folder): ", SC2SpikeInterfaceFoldertoSeach);
                        StringtoShow = [StringtoShow;"";"Searching for sorting output results (.npy files) that are used to start phy (either with native Kilosort ouput files or with Spikeinterface using the 'export to Phy' functionality). Scaling factor only needed when loading Kilosort output files.";"";"Note: When loading SpikeInterface results, a 'SpikePositions'.mat file is needed holding the spike positions in um. When Spikeinterface sorting is done in this GUI, it will be created automatically."];
                        app.Path = SC2SpikeInterfaceFoldertoSeach;
                        app.CurrentSorter = "SpykingCircus2";
                    end
                else
                    if isfolder(app.Mainapp.Data.Info.Data_Path)
                        [stringArray] = Utility_Extract_Contents_of_Folder(app.Mainapp.Data.Info.Data_Path);
                        StringtoShow = strcat("Auto-searched Path for sorting output files (Original Recording Folder): ", app.Mainapp.Data.Info.Data_Path);
                        StringtoShow = [StringtoShow;"";"Searching for sorting output results (.npy files) that are used to start phy (either with native Kilosort ouput files or with Spikeinterface using the 'export to Phy' functionality). Scaling factor only needed when loading Kilosort output files.";"";"Note: When loading SpikeInterface results, a 'SpikePositions'.mat file is needed holding the spike positions in um. When Spikeinterface sorting is done in this GUI, it will be created automatically."];
                        app.Path = app.Mainapp.Data.Info.Data_Path;
                    else
                        StringtoShow = "Auto-searched path the recording was extracted from was not found or does not exist anymore. Please select a folder manually containing sorting output results (.npy files) that are used to start phy (either with native Kilosort ouput files or with Spikeinterface using the 'export to Phy' functionality). Scaling factor only needed when loading Kilosort output files.";
                        StringtoShow = [StringtoShow; "";"Scaling factor to convert int format of kilosort output to mV not found. Enter manually or select folder containing the scalingfactor (autosaved when saving dataset for kilosort)."];
                        app.InformationTextArea.Value = StringtoShow;
                        app.Path = [];
                        return;
                    end
                end
            end

            % Filter files with .npy extension
            npyFiles = stringArray(endsWith(stringArray, '.npy'));

            if isempty(npyFiles)
                StringtoShow = ["Warning: No .npy output files found in auto-searched folder. Please select a different folder."; ""; StringtoShow];
            else
                StringtoShow = [StringtoShow; "";".npy output files found. Folder contents are:"; stringArray];
            end
                        
            if strcmp(app.CurrentSorter,"Kilosort4") || strcmp(app.CurrentSorter,"Kilosort3")
                if isfield(app.Mainapp.Data.Info,'KilosortScalingFactor')
                    app.AmplitudeScalingFactorEditField.Value = num2str(app.Mainapp.Data.Info.KilosortScalingFactor);
                    StringtoShow = [StringtoShow; "";"Scaling factor to convert int format of kilosort output to mV found as part of the recording info."];
                else
                    ScalingFactorPath32 = strcat(app.Mainapp.Data.Info.Data_Path,'\Kilosort\Scaling Factor int32.mat');
                    ScalingFactorPath16 = strcat(app.Mainapp.Data.Info.Data_Path,'\Kilosort\Scaling Factor int16.mat');
                    if isfile(ScalingFactorPath32) 
                        %% Check if selected mat file contains correct variable
                        variableName = 'scalingFactor';  % Variable you want to load
                        
                        % Get the list of variables in the file
                        variablesInFile = who('-file', ScalingFactorPath32);
                        
                        % Check if the desired variable exists
                        if ismember(variableName, variablesInFile)
                            load(ScalingFactorPath32, variableName);  % Load only the specific variable
                        else
                            msgbox(strcat("Variable ", variableName," does not exist in the manually selected file ",ScalingFactorPath32));
                            return;  % Exit if the variable does not exist
                        end
    
                        load(ScalingFactorPath32,'scalingFactor');
                        StringtoShow = [StringtoShow; "Scaling factor to convert int format of kilosort output to mV found in 'Original_Recording_Path/Kilosort' saved as a .mat file."];
                        app.AmplitudeScalingFactorEditField.Value = num2str(scalingFactor);
                        app.Mainapp.Data.Info.KilosortScalingFactor = scalingFactor;
                    elseif isfile(ScalingFactorPath16) 
                        %% Check if selected mat file contains correct variable
                        variableName = 'scalingFactor';  % Variable you want to load
                        
                        % Get the list of variables in the file
                        variablesInFile = who('-file', ScalingFactorPath16);
                        
                        % Check if the desired variable exists
                        if ismember(variableName, variablesInFile)
                            load(ScalingFactorPath16, variableName);  % Load only the specific variable
                        else
                            msgbox(strcat("Variable ", variableName," does not exist in the manually selected file ",ScalingFactorPath16));
                            return;  % Exit if the variable does not exist
                        end
    
                        load(ScalingFactorPath16,'scalingFactor');
                        StringtoShow = [StringtoShow; "Scaling factor to convert int format of kilosort output to mV found in 'Original_Recording_Path/Kilosort' saved as a .mat file."];
                        app.AmplitudeScalingFactorEditField.Value = num2str(scalingFactor);
                        app.Mainapp.Data.Info.KilosortScalingFactor = scalingFactor;
                    else
                        StringtoShow = [StringtoShow; "Scaling factor to convert int format of kilosort output to mV was not found as part of the dataset or as a .mat file in 'Original_Recording_Path/Kilosort'. Select a folder containing a .mat file with a variable called scalingfactor (autosaved when saving dataset for kilosort), enter the scalingfactor manually or leave it empty (for no scaling)"];      
                        app.AmplitudeScalingFactorEditField.Value = "";
                    end
    
                end
            end
            app.InformationTextArea.Value = StringtoShow;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            if isempty(app.Path)
                app.InformationTextArea.Value = "No Path selected. Please first select a folder.";
                return;
            end

            if ~isfolder(app.Path)
                app.InformationTextArea.Value = "Selected Path does not exist. Please select an exisitng folder.";
                return;
            end
            
            if isempty(app.AmplitudeScalingFactorEditField.Value)
                KilosortScalingFactor = [];
            else
                KilosortScalingFactor = str2double(app.AmplitudeScalingFactorEditField.Value);
            end
            
            if strcmp(app.SpikeSorterDropDown.Value,"SpikeInterface Phy Output for Mountainsort or SpikingCircus")
                [app.Mainapp.Data,SaveFilter] = Spike_Module_Load_SpikeInterface_Sorter(app.Mainapp.Data,app.Path);
            end

            if strcmp(app.SpikeSorterDropDown.Value,"Kilosort")
                % Function to load all relevant npy and .mat files Kilosort outputs
                [app.Mainapp.Data,SaveFilter] = Spike_Module_Load_Kilosort_Data(app.Mainapp.Data,"No",app.Path,KilosortScalingFactor);
            end

            app.Mainapp.UIAxes.NextPlot = "replace"; 
            plot(app.Mainapp.UIAxes,0,0);
            app.Mainapp.UIAxes.NextPlot = "add"; 
            app.Mainapp.UIAxes_2.NextPlot = "replace"; 
            plot(app.Mainapp.UIAxes_2,0,0);
            app.Mainapp.UIAxes_2.NextPlot = "add"; 

            %Take care of potentially changed backgroundcolor
            app.Mainapp.UIAxes.Color = app.Mainapp.PlotAppearance.MainWindow.Data.Color.MainBackground;
            app.Mainapp.UIAxes_2.Color = app.Mainapp.PlotAppearance.MainWindow.Data.Color.TimeBackground;
            app.Mainapp.UIAxes.FontSize = app.Mainapp.PlotAppearance.MainWindow.Data.MainFontSize;
            app.Mainapp.UIAxes_2.FontSize = app.Mainapp.PlotAppearance.MainWindow.Data.TimeFontSize;
            
            if ~isfield(app.Mainapp.Data,'Events')
                if strcmp(app.Mainapp.PlotEvents,"Events")
                    app.Mainapp.PlotEvents = "No";
                end
                Placeholder = {};
                app.Mainapp.DropDown_2.Items = Placeholder;
                app.Mainapp.DropDown_2.Items{1} = 'Non';
            else
                Placeholder = {};
                app.Mainapp.DropDown_2.Items = Placeholder;
                app.Mainapp.DropDown_2.Items{1} = 'Non';
                app.Mainapp.DropDown_2.Items{2} = 'Events';
                if strcmp(app.Mainapp.PlotEvents,"Events")
                    app.Mainapp.DropDown_2.Value = 'Events';
                end
            end
        
            if ~isfield(app.Mainapp.Data,'Spikes')
                % if strcmp(app.Mainapp.Plotspikes,"Spikes")
                %     app.Mainapp.Plotspikes = "No";
                % end
            else
                app.Mainapp.DropDown_2.Items = Placeholder;
                app.Mainapp.DropDown_2.Items{1} = 'Non';
                if isfield(app.Mainapp.Data,'Events')
                    app.Mainapp.DropDown_2.Items{2} = 'Events';
                    app.Mainapp.DropDown_2.Items{3} = 'Spikes';
                else
                    app.Mainapp.DropDown_2.Items{2} = 'Spikes';
                end
                
                if strcmp(app.Mainapp.PlotEvents,"Events")
                    app.Mainapp.DropDown_2.Value = 'Events';
                else
                    app.Mainapp.DropDown_2.Value = 'Non';
                end
            end

            if strcmp(app.Mainapp.Plotspikes,"Spikes")
                SpikeHandles = findobj(app.Mainapp.UIAxes, 'Type', 'line', 'Tag', 'Spikes');
                if ~isempty(SpikeHandles)
                    delete(SpikeHandles(:));
                end
                app.Mainapp.Plotspikes = "No";
            end

            Utility_Show_Info_Loaded_Data(app.Mainapp);
            
            if strcmp(SaveFilter,"Yes")
                [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],[]);
            end

            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","SpikeExtraction",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);

            delete(app);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.Mainapp.LoadfromKilosortWindowWindow = [];
            delete(app);
        end

        % Button pushed function: SelectKilosortFolderManuallyButton
        function SelectKilosortFolderManuallyButtonPushed(app, event)
            % msgbox("Please selecet a folder containing Kilosort Data");

            % Prompt user to select a folder
            folderPath = uigetdir('Select a folder');
            
            % Check if user cancels the dialog
            if folderPath == 0
                disp('Operation canceled by user');
                return;
            end

            StringtoShow = [];

            if isfolder(folderPath)
                [stringArray] = Utility_Extract_Contents_of_Folder(folderPath);
                StringtoShow = strcat("Manually selected path: ", folderPath);
                app.Path = folderPath;
            else
                if isfolder(app.Mainapp.Data.Info.Data_Path)
                    [stringArray] = Utility_Extract_Contents_of_Folder(app.Mainapp.Data.Info.Data_Path);
                    StringtoShow = strcat("Manually selected path: ", app.Mainapp.Data.Info.Data_Path);
                    app.Path = app.Mainapp.Data.Info.Data_Path;
                else
                    app.InformationTextArea.Value = "Manually selected path is not available. Please select a folder.";
                    stringArray = [];
                    app.Path = [];
                    return;
                end
            end

            % Filter files with .npy extension
            npyFiles = stringArray(endsWith(stringArray, '.npy'));

            if isempty(npyFiles)
                StringtoShow = ["Warning: No .npy kilosort output files found. Please select a different folder."; ""; StringtoShow];
            else
                StringtoShow = [StringtoShow; "";".npy kilosort output files found. Folder contents are:"; stringArray];
            end
                        
            if isfield(app.Mainapp.Data.Info,'KilosortScalingFactor')
                app.AmplitudeScalingFactorEditField.Value = num2str(app.Mainapp.Data.Info.KilosortScalingFactor);
                StringtoShow = [StringtoShow; "";"Scaling factor to convert int format of the kilosort output to mV found as part of the recording info."];
            else
                ScalingFactorPath32 = strcat(app.Mainapp.Data.Info.Data_Path,'\Kilosort\Scaling Factor int32.mat');
                ScalingFactorPath16 = strcat(app.Mainapp.Data.Info.Data_Path,'\Kilosort\Scaling Factor int16.mat');
                if isfile(ScalingFactorPath32) 

                    %% Check if selected mat file contains correct variable
                    variableName = 'scalingFactor';  % Variable you want to load
                    
                    % Get the list of variables in the file
                    variablesInFile = who('-file', ScalingFactorPath32);
                    
                    % Check if the desired variable exists
                    if ismember(variableName, variablesInFile)
                        load(ScalingFactorPath32, variableName);  % Load only the specific variable
                    else
                        msgbox(strcat("Variable ", variableName," does not exist in the manually selected file ",ScalingFactorPath32));
                        return;  % Exit if the variable does not exist
                    end

                    load(ScalingFactorPath32,'scalingFactor');
                    StringtoShow = [StringtoShow;""; "Scaling factor to convert int format of the kilosort output to mV found in 'Original_Recording_Path/Kilosort' saved as a .mat file."];
                    app.AmplitudeScalingFactorEditField.Value = num2str(scalingFactor);
                    app.Mainapp.Data.Info.KilosortScalingFactor = scalingFactor;
                elseif isfile(ScalingFactorPath16) 

                    %% Check if selected mat file contains correct variable
                    variableName = 'scalingFactor';  % Variable you want to load
                    
                    % Get the list of variables in the file
                    variablesInFile = who('-file', ScalingFactorPath16);
                    
                    % Check if the desired variable exists
                    if ismember(variableName, variablesInFile)
                        load(ScalingFactorPath16, variableName);  % Load only the specific variable
                    else
                        msgbox(strcat("Variable ", variableName," does not exist in the manually selected file ",ScalingFactorPath16));
                        return;  % Exit if the variable does not exist
                    end

                    load(ScalingFactorPath16,'scalingFactor');
                    StringtoShow = [StringtoShow;""; "Scaling factor to convert int format of the kilosort output to mV found in 'Original_Recording_Path/Kilosort' saved as a .mat file."];
                    app.AmplitudeScalingFactorEditField.Value = num2str(scalingFactor);
                    app.Mainapp.Data.Info.KilosortScalingFactor = scalingFactor;
                else
                    StringtoShow = [StringtoShow;""; "Scaling factor to convert int format of the kilosort output to mV was not found as part of the dataset or as a .mat file in 'Original_Recording_Path/Kilosort'. Select a different folder, enter the scalingfactor manually or leave it empty (for no scaling)"];      
                    app.AmplitudeScalingFactorEditField.Value = "";
                end

            end

            app.InformationTextArea.Value = StringtoShow;
        end

        % Button pushed function: SelectAmplitudeScalingManuallyButton
        function SelectAmplitudeScalingManuallyButtonPushed(app, event)
            % Prompt user to select a folder
            filePath = uigetfile('Select a folder');
            
            % Check if user cancels the dialog
            if filePath == 0
                disp('Operation canceled by user');
                return;
            end
            
            %% Check if selected mat file contains correct variable
            variableName = 'scalingFactor';  % Variable you want to load
            
            % Get the list of variables in the file
            variablesInFile = who('-file', filePath);
            
            % Check if the desired variable exists
            if ismember(variableName, variablesInFile)
                load(filePath, variableName);  % Load only the specific variable
            else
                msgbox(strcat("Variable ", variableName," does not exist in the manually selected file ",filePath));
                return;  % Exit if the variable does not exist
            end

            load(filePath,'scalingFactor');

            scalindicie = find(contains(app.InformationTextArea.Value, 'Scaling'));
            % cut out previous scaling info shown
            StringtoShow = app.InformationTextArea.Value(1:scalindicie(1)-1);
            % add new scaling info 
            StringtoShow = [StringtoShow; "Scaling factor to convert int format of the kilosort output to mV found in manually selected a .mat file."];


            app.AmplitudeScalingFactorEditField.Value = num2str(scalingFactor);
            app.Mainapp.Data.Info.KilosortScalingFactor = scalingFactor;

            app.InformationTextArea.Value = StringtoShow;
        end

        % Close request function: LoadSpikeSortingResultsUIFigure
        function LoadSpikeSortingResultsUIFigureCloseRequest(app, event)
            app.Mainapp.LoadfromKilosortWindowWindow = [];
            delete(app);
        end

        % Value changed function: SpikeSorterDropDown
        function SpikeSorterDropDownValueChanged(app, event)
            value = app.SpikeSorterDropDown.Value;
            if strcmp(value,'Kilosort')
            else
                app.AmplitudeScalingFactorEditField.Value = "";
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.LoadSpikeSortingResultsUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {359, 359};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LoadSpikeSortingResultsUIFigure = uifigure('Visible', 'off');
            app.LoadSpikeSortingResultsUIFigure.Name = 'Load Spike Sorting Results';
            app.LoadSpikeSortingResultsUIFigure.Icon = 'Logo.png';
            app.LoadSpikeSortingResultsUIFigure.CloseRequestFcn = createCallbackFcn(app, @LoadSpikeSortingResultsUIFigureCloseRequest, true);
            app.LoadSpikeSortingResultsUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.LoadSpikeSortingResultsUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.LoadSpikeSortingResultsLabel = uilabel(app.RightPanel);
            app.LoadSpikeSortingResultsLabel.Text = 'Load Spike Sorting Results';
            app.NeuromodLabel = uilabel(app.RightPanel);
            app.NeuromodLabel.Text = 'Neuromod';
            app.CloseButton = uibutton(app.RightPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.InformationTextAreaLabel = uilabel(app.RightPanel);
            app.InformationTextAreaLabel.Text = 'Information';
            app.InformationTextArea = uitextarea(app.RightPanel);
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = 'Logo.png';
            app.LoadingOptionsPanel = uipanel(app.RightPanel);
            app.LoadingOptionsPanel.Title = 'Loading Options';
            app.SelectKilosortFolderManuallyButton = uibutton(app.LoadingOptionsPanel, 'push');
            app.SelectKilosortFolderManuallyButton.ButtonPushedFcn = createCallbackFcn(app, @SelectKilosortFolderManuallyButtonPushed, true);
            app.SelectKilosortFolderManuallyButton.Text = {'Select Results'; 'Folder Manually'};
            app.AmplitudeScalingFactorEditFieldLabel = uilabel(app.LoadingOptionsPanel);
            app.AmplitudeScalingFactorEditFieldLabel.Text = {'Amplitude'; 'Scaling Factor'};
            app.AmplitudeScalingFactorEditField = uieditfield(app.LoadingOptionsPanel, 'text');
            app.EmptyfornonLabel = uilabel(app.LoadingOptionsPanel);
            app.EmptyfornonLabel.Text = '(Empty for non)';
            app.SelectAmplitudeScalingManuallyButton = uibutton(app.LoadingOptionsPanel, 'push');
            app.SelectAmplitudeScalingManuallyButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAmplitudeScalingManuallyButtonPushed, true);
            app.SelectAmplitudeScalingManuallyButton.Text = {'Select Amplitude'; 'Scaling Manually'};
            app.SpikeSorterDropDownLabel = uilabel(app.LoadingOptionsPanel);
            app.SpikeSorterDropDownLabel.Text = 'SpikeSorter';
            app.SpikeSorterDropDown = uidropdown(app.LoadingOptionsPanel);
            app.SpikeSorterDropDown.Items = {'Kilosort', 'SpikeInterface Mountainsort5 or SpykingCircus2 Results '};
            app.SpikeSorterDropDown.ValueChangedFcn = createCallbackFcn(app, @SpikeSorterDropDownValueChanged, true);
            app.SpikeSorterDropDown.Value = 'Kilosort';
            app.LoadButton = uibutton(app.LoadingOptionsPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            % Show the figure after all components are created
            app.LoadSpikeSortingResultsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Kilosort_Loadfile_Format_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.LoadSpikeSortingResultsUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.LoadSpikeSortingResultsUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LoadSpikeSortingResultsUIFigure)
        end
    end
end