classdef Save_for_Spike_Sorting_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SaveforSpikeSortingWindowUIFigure  matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        RightPanel                  matlab.ui.container.Panel
        InformationTextArea         matlab.ui.control.TextArea
        InformationTextAreaLabel    matlab.ui.control.Label
        SavingOptionsPanel          matlab.ui.container.Panel
        notrecommendedLabel         matlab.ui.control.Label
        SaveButton                  matlab.ui.control.Button
        Dataset                     matlab.ui.control.DropDown
        DatasetLabel                matlab.ui.control.Label
        SaveFormatDropDown_2        matlab.ui.control.DropDown
        FileTypeLabel               matlab.ui.control.Label
        SelectFolderManuallyButton  matlab.ui.control.Button
        SaveFormatDropDown          matlab.ui.control.DropDown
        FormatDropDownLabel         matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        CloseButton                 matlab.ui.control.Button
        NeuromodLabel               matlab.ui.control.Label
        SaveforSpikeSortingLabel    matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Mainapp=[]; % Description
        StandardFolder = [];
        SavePath = [];
        filename = [];
        ManualSelection = 0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            movegui(app.SaveforSpikeSortingWindowUIFigure, 'center');
            app.Mainapp = Mainapp;

            if isfield(app.Mainapp.Data,'Preprocessed')
                if ~isempty(app.Mainapp.Data.Preprocessed)
                    app.Dataset.Items{2} = 'Preprocessed Data';
                else

                end
            else

            end

            FoldertoSeach = strcat(app.Mainapp.Data.Info.Data_Path,'\Kilosort\');
            dashindex = find(FoldertoSeach=='\');
            driveLetter = FoldertoSeach(1:dashindex(1)-1);

            StringtoShow = [];

            if ~isfolder(driveLetter)
                disp(['Drive ' driveLetter ' does not exist.']);
                StringtoShow = ["Save Raw or Preprocessed Data to load in Kilosort (use externally in Kilosort GUI yourself) or Spikeinterface (use externally with your own python script or use this GUI to conduct SpikeInterface sorting automatically).";"";"Auto - selection of path failed since drive letter was not found. Please select a folder manually.";"Note: When saved in int format, a scaling factor is saved in .mat file to convert back into the original scale."];
                app.SavePath = [];
            else
                StringtoShow = ["Save Raw or Preprocessed Data to load in Kilosort (use externally in Kilosort GUI yourself) or Spikeinterface (use externally with your own python script or use this GUI to conduct SpikeInterface sorting automatically).";"";"Auto - selected save path: ";"";convertCharsToStrings(FoldertoSeach);"";"save in this folder to directly be able to conduct spike sorting with SpikeInterface in this GUI without selecting any folders or if you want to use the Autorun function of this GUI with the saved files. Loading spike sortings will also work automatically without selecting files if you save in this folder (Folder will be created when non existent)";"Note: When saved in int format, a scaling factor is saved in .mat file to convert back into the original scale."];
                app.SavePath = convertStringsToChars(app.Mainapp.Data.Info.Data_Path);
                dahsindicie = find(app.Mainapp.Data.Info.Data_Path=='\');
                app.filename = strcat(app.Mainapp.Data.Info.Data_Path(dahsindicie(end)+1:end),".dat");
                StringtoShow = [StringtoShow;"";"Filename:";"";app.filename];
                StringtoShow = [StringtoShow;"";"When loading into Kilosort make sure to set Kilosort settings to the 'int' type you selected."];
            end

            app.InformationTextArea.Value = StringtoShow;

            pause(0.1);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if isempty(app.SavePath)
                StringtoShow = "Auto - selected of path failed since drive letter was not found. Please select a folder manually.";
                app.InformationTextArea.Value = StringtoShow;
                return;
            end
            
            dashindex = find(app.SavePath=='\');
            driveLetter = app.SavePath(1:dashindex(1)-1);

            if isfolder(driveLetter)
                % if app.ManualSelection == 0
                if strcmp(app.SaveFormatDropDown_2.Value,'External Kilosort GUI')
                    Checkpath = convertStringsToChars(strcat(app.SavePath,'\Kilosort\'));
                    Path = convertStringsToChars(strcat(app.SavePath,'\Kilosort\',app.filename));
                else
                    Checkpath = convertStringsToChars(strcat(app.SavePath,'\SpikeInterface\'));
                    Path = convertStringsToChars(strcat(app.SavePath,'\SpikeInterface\',app.filename));
                end
                % else
                %     Checkpath = strcat(app.SavePath);
                %     Path = fullfile(app.SavePath,app.filename);
                % end

                if ~isfolder(Checkpath)
                    % Create a folder
                    [status, msg, msgID] = mkdir(Checkpath);
                
                    % Check the status and print an appropriate message
                    if status == 1
                        fprintf('The folder "%s" has been successfully created.\n', Checkpath);
                    else
                        fprintf('Failed to create the folder "%s".\nError: %s\n', Checkpath, msg);
                    end
                end

                % Function to Save Raw Data as int16 in a .dat file
                [app.Mainapp.Data] = Spike_Module_Save_for_Kilosort(app.Mainapp.Data,"No",Path,app.SaveFormatDropDown.Value,app.SaveFormatDropDown_2.Value,app.Dataset.Value);

            else
                msgbox("Error: Manully or auto-selected folder cannot be accessed! Please first select a valid folder.")
                return;
            end
            
            Utility_Show_Info_Loaded_Data(app.Mainapp);

            delete(app);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.Mainapp.SaveforKilosortWindowWindow = [];
            delete(app);
        end

        % Button pushed function: SelectFolderManuallyButton
        function SelectFolderManuallyButtonPushed(app, event)
            startPath = app.Mainapp.executableFolder; % Set your desired starting path
            selectedFolder = uigetdir(startPath, 'Select a Folder');
            
            if selectedFolder == 0
                disp('User canceled folder selection.');
                return;
            else
                disp(['Selected folder: ', selectedFolder]);
            end
            
            if strcmp(app.SaveFormatDropDown_2.Value,"External Kilosort GUI")
                fullFilePath = convertStringsToChars(strcat(selectedFolder,"\Kilosort"));
            elseif strcmp(app.SaveFormatDropDown_2.Value,"SpikeInterface")
                fullFilePath = convertStringsToChars(strcat(selectedFolder,"\SpikeInterface"));
            end


            StringtoShow = ["Save Raw or Preprocessed Data to load in Kilosort (use externally in Kilosort GUI yourself) or Spikeinterface (use externally with your own python script or use this GUI to conduct SpikeInterface sorting automatically).";"";"Manually - selected save path: ";"";convertCharsToStrings(fullFilePath);"";"save in this folder to directly be able to conduct spike sorting with SpikeInterface in this GUI without selecting any folders or if you want to use the Autorun function of this GUI with the saved files. Loading spike sortings will also work automatically without selecting files if you save in this folder (Folder will be created when non existent)";"Note: When saved in int format, a scaling factor is saved in .mat file to convert back into the original scale."];
            
            app.SavePath = convertStringsToChars(selectedFolder);

            dahsindicie = find(app.Mainapp.Data.Info.Data_Path=='\');

            if strcmp(app.SaveFormatDropDown_2.Value,"External Kilosort GUI")
                app.filename = convertStringsToChars(strcat(app.Mainapp.Data.Info.Data_Path(dahsindicie(end)+1:end),".dat"));
            elseif strcmp(app.SaveFormatDropDown_2.Value,"SpikeInterface")
                app.filename = convertStringsToChars(strcat(app.Mainapp.Data.Info.Data_Path(dahsindicie(end)+1:end),".bin"));
            end

            StringtoShow = [StringtoShow;"";"Filename:";"";app.filename];
       
            app.InformationTextArea.Value = StringtoShow;

            pause(0.1);
        end

        % Close request function: SaveforSpikeSortingWindowUIFigure
        function SaveforSpikeSortingWindowUIFigureCloseRequest(app, event)
            app.Mainapp.SaveforKilosortWindowWindow = [];
            delete(app);
        end

        % Value changed function: SaveFormatDropDown_2
        function SaveFormatDropDown_2ValueChanged(app, event)
            value = app.SaveFormatDropDown_2.Value;
            if isempty(app.SavePath)
                msgbox("Error: Manully or auto-selected folder cannot be accessed! Please first select a valid folder.")
                    return;
            else
                dashindex = find(app.SavePath=='\');
                driveLetter = app.SavePath(1:dashindex(1)-1);

                if ~isfolder(driveLetter)
                    msgbox("Error: Manully or auto-selected folder cannot be accessed! Please first select a valid folder.")
                    return;
                end
            end

            if strcmp(value,'SpikeInterface')
                
                app.Dataset.Items = {};
                app.Dataset.Items{1} = 'Raw Data';

                Placeholder = {};
                app.SaveFormatDropDown.Items = Placeholder;
                app.SaveFormatDropDown.Items{1} = 'double';
                
                if app.ManualSelection == 0
                    FoldertoShow = strcat(fullfile(app.SavePath),'\SpikeInterface');
                else
                    FoldertoShow = app.SavePath;
                end
                
                if ~ischar(app.filename)
                    app.filename = convertStringsToChars(app.filename);
                end

                dotindex = find(app.filename=='.');
                app.filename(dotindex(1):end) = [];
                app.filename = strcat(app.filename,'.bin');
                if app.ManualSelection == 0
                    StringtoShow = ["Save Raw or Preprocessed Data to load in Kilosort (use externally in Kilosort GUI yourself) or Spikeinterface (use externally with your own python script or use this GUI to conduct SpikeInterface sorting automatically).";"";"Auto - selected save path: ";"";convertCharsToStrings(FoldertoShow);"";"Save in this folder to load SpikeInterface output directly without manually selecting a folder (Folder will be created when non existent)";"Note: When saved in int format, a scaling factor is saved in .mat file to convert back into the original scale."];
                else
                    StringtoShow = ["Save Raw or Preprocessed Data to load in Kilosort (use externally in Kilosort GUI yourself) or Spikeinterface (use externally with your own python script or use this GUI to conduct SpikeInterface sorting automatically).";"";"Manually - selected save path: ";"";convertCharsToStrings(FoldertoShow);"";"Save in this folder to load SpikeInterface output directly without manually selecting a folder (Folder will be created when non existent)";"Note: When saved in int format, a scaling factor is saved in .mat file to convert back into the original scale."];
                end
                StringtoShow = [StringtoShow;"";"Filename:";"";app.filename];
                StringtoShow = [StringtoShow;"";"When loading into Kilosort make sure to set Kilosort settings to the 'int' type you selected."];
                
                app.InformationTextArea.Value = StringtoShow;
            else
                
                if isfield(app.Mainapp.Data,'Preprocessed')
                    app.Dataset.Items = {};
                    app.Dataset.Items{1} = 'Raw Data';
                    app.Dataset.Items{2} = 'Preprocessed Data';
                end

                Placeholder = {};
                app.SaveFormatDropDown.Items = Placeholder;
                app.SaveFormatDropDown.Items{1} = 'int16'; 
                app.SaveFormatDropDown.Items{2} = 'int32';

                if app.ManualSelection == 0
                    FoldertoShow = convertStringsToChars(strcat(fullfile(app.SavePath),'\Kilosort'));
                else
                    FoldertoShow = app.SavePath;
                end

                dotindex = find(app.filename=='.');
                app.filename(dotindex(1):end) = [];
                app.filename = convertStringsToChars(strcat(app.filename,'.dat'));
                
                if app.ManualSelection == 0
                    StringtoShow = ["Save Raw or Preprocessed Data to load in Kilosort (use externally in Kilosort GUI yourself) or Spikeinterface (use externally with your own python script or use this GUI to conduct SpikeInterface sorting automatically).";"";"Auto - selected save path: ";"";convertCharsToStrings(FoldertoShow);"";"save in this folder to directly be able to conduct spike sorting with SpikeInterface in this GUI without selecting any folders or if you want to use the Autorun function of this GUI with the saved files. Loading spike sortings will also work automatically without selecting files if you save in this folder (Folder will be created when non existent)";"Note: When saved in int format, a scaling factor is saved in .mat file to convert back into the original scale."];
                else
                    StringtoShow = ["Save Raw or Preprocessed Data to load in Kilosort (use externally in Kilosort GUI yourself) or Spikeinterface (use externally with your own python script or use this GUI to conduct SpikeInterface sorting automatically).";"";"Manaully - selected save path: ";"";convertCharsToStrings(FoldertoShow);"";"save in this folder to directly be able to conduct spike sorting with SpikeInterface in this GUI without selecting any folders or if you want to use the Autorun function of this GUI with the saved files. Loading spike sortings will also work automatically without selecting files if you save in this folder (Folder will be created when non existent)";"Note: When saved in int format, a scaling factor is saved in .mat file to convert back into the original scale."];
                end
                StringtoShow = [StringtoShow;"";"Filename:";"";app.filename];
                StringtoShow = [StringtoShow;"";"When loading into Kilosort make sure to set Kilosort settings to the 'int' type you selected."];
            
                app.InformationTextArea.Value = StringtoShow;
            end
        end

        % Value changed function: SaveFormatDropDown
        function SaveFormatDropDownValueChanged(app, event)
           
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SaveforSpikeSortingWindowUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {491, 491};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SaveforSpikeSortingWindowUIFigure = uifigure('Visible', 'off');
            app.SaveforSpikeSortingWindowUIFigure.Name = 'Save for Spike Sorting Window';
            app.SaveforSpikeSortingWindowUIFigure.Icon = 'Logo.png';
            app.SaveforSpikeSortingWindowUIFigure.CloseRequestFcn = createCallbackFcn(app, @SaveforSpikeSortingWindowUIFigureCloseRequest, true);
            app.SaveforSpikeSortingWindowUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.SaveforSpikeSortingWindowUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.SaveforSpikeSortingLabel = uilabel(app.RightPanel);
            app.SaveforSpikeSortingLabel.Text = 'Save for Spike Sorting';
            app.NeuromodLabel = uilabel(app.RightPanel);
            app.NeuromodLabel.Text = 'Neuromod';
            app.CloseButton = uibutton(app.RightPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = 'Logo.png';
            app.SavingOptionsPanel = uipanel(app.RightPanel);
            app.SavingOptionsPanel.Title = 'Saving Options';
            app.FormatDropDownLabel = uilabel(app.SavingOptionsPanel);
            app.FormatDropDownLabel.Text = 'Format';
            app.SaveFormatDropDown = uidropdown(app.SavingOptionsPanel);
            app.SaveFormatDropDown.Items = {'int16', 'int32'};
            app.SaveFormatDropDown.ValueChangedFcn = createCallbackFcn(app, @SaveFormatDropDownValueChanged, true);
            app.SaveFormatDropDown.Value = 'int16';
            app.SelectFolderManuallyButton = uibutton(app.SavingOptionsPanel, 'push');
            app.SelectFolderManuallyButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderManuallyButtonPushed, true);
            app.SelectFolderManuallyButton.Text = 'Select Save Folder Manually';
            app.FileTypeLabel = uilabel(app.SavingOptionsPanel);
            app.FileTypeLabel.Text = 'Spike Sorter';
            app.SaveFormatDropDown_2 = uidropdown(app.SavingOptionsPanel);
            app.SaveFormatDropDown_2.Items = {'External Kilosort GUI', 'SpikeInterface'};
            app.SaveFormatDropDown_2.ValueChangedFcn = createCallbackFcn(app, @SaveFormatDropDown_2ValueChanged, true);
            app.SaveFormatDropDown_2.Value = 'External Kilosort GUI';
            app.DatasetLabel = uilabel(app.SavingOptionsPanel);
            app.DatasetLabel.Text = 'Dataset';
            app.Dataset = uidropdown(app.SavingOptionsPanel);
            app.Dataset.Items = {'Raw Data'};
            app.Dataset.Value = 'Raw Data';
            app.SaveButton = uibutton(app.SavingOptionsPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.notrecommendedLabel = uilabel(app.SavingOptionsPanel);
            app.notrecommendedLabel.Text = 'not recommended!';
            app.InformationTextAreaLabel = uilabel(app.RightPanel);
            app.InformationTextAreaLabel.Text = 'Information';
            app.InformationTextArea = uitextarea(app.RightPanel);
            % Show the figure after all components are created
            app.SaveforSpikeSortingWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Save_for_Spike_Sorting_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SaveforSpikeSortingWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.SaveforSpikeSortingWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SaveforSpikeSortingWindowUIFigure)
        end
    end
end