classdef Continous_Kilosort_Spike_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ContinousKilosortSpikeAnalysisUIFigure  matlab.ui.Figure
        SnapshotMenu                    matlab.ui.container.Menu
        SaveSnapshotofWindowMenu        matlab.ui.container.Menu
        PNGMenu                         matlab.ui.container.Menu
        JPGMenu                         matlab.ui.container.Menu
        PlotMenu                        matlab.ui.container.Menu
        Show2DimensionalPlotsMenu       matlab.ui.container.Menu
        Show3DimensionalPlotsMenu       matlab.ui.container.Menu
        ExportDataasMenu                matlab.ui.container.Menu
        SpikeRateMenu                   matlab.ui.container.Menu
        AllSpikesMenu                   matlab.ui.container.Menu
        OverTimeMenu                    matlab.ui.container.Menu
        matMenu_5                       matlab.ui.container.Menu
        txtMenu_6                       matlab.ui.container.Menu
        csvMenu_6                       matlab.ui.container.Menu
        OverChannelMenu                 matlab.ui.container.Menu
        matMenu_6                       matlab.ui.container.Menu
        txtMenu_7                       matlab.ui.container.Menu
        csvMenu_7                       matlab.ui.container.Menu
        SelectedUnitMenu                matlab.ui.container.Menu
        matMenu_2                       matlab.ui.container.Menu
        txtMenu_3                       matlab.ui.container.Menu
        csvMenu_3                       matlab.ui.container.Menu
        MainPlotMenu                    matlab.ui.container.Menu
        AllSpikesMenu_2                 matlab.ui.container.Menu
        matMenu_3                       matlab.ui.container.Menu
        txtMenu_4                       matlab.ui.container.Menu
        csvMenu_4                       matlab.ui.container.Menu
        SelectedUnitMenu_2              matlab.ui.container.Menu
        matMenu_4                       matlab.ui.container.Menu
        txtMenu_5                       matlab.ui.container.Menu
        csvMenu_5                       matlab.ui.container.Menu
        Panel_2                         matlab.ui.container.Panel
        WaveformSelectionforPlottingEditField  matlab.ui.control.EditField
        WaveformSelectionforPlottingEditFieldLabel  matlab.ui.control.Label
        CloseButton                     matlab.ui.control.Button
        NeuromodToolboxLabel            matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        KilosortAnalysisWindowLabel     matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        ChannelSelectionforPlottingEditField  matlab.ui.control.EditField
        ChannelSelectionforPlottingEditFieldLabel  matlab.ui.control.Label
        TimeWindowSpiketriggredLFPEditField  matlab.ui.control.EditField
        TimeWindowSpiketriggredLFPEditFieldLabel  matlab.ui.control.Label
        TypeofAnalysisDropDown          matlab.ui.control.DropDown
        TypeofAnalysisLabel             matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        ClustertoshowDropDown           matlab.ui.control.DropDown
        ClustertoShowDropDownLabel      matlab.ui.control.Label
        EventstoshowDropDown            matlab.ui.control.DropDown
        EventstoShowDropDownLabel       matlab.ui.control.Label
        SpikeRateNumBinsEditField       matlab.ui.control.EditField
        SpikeRateNumBinsEditFieldLabel  matlab.ui.control.Label
        UIAxes_5                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        ContextMenu3                    matlab.ui.container.ContextMenu
        ChangeTitleMenu                 matlab.ui.container.Menu
        ChangeXLabelMenu                matlab.ui.container.Menu
        ChangeYLabelMenu                matlab.ui.container.Menu
        ChangeXlimMenu                  matlab.ui.container.Menu
        ChangeYlimMenu                  matlab.ui.container.Menu
        ChangeLegendMenu                matlab.ui.container.Menu
        ChangeFontsizeMenu              matlab.ui.container.Menu
        ContextMenu3_2                  matlab.ui.container.ContextMenu
        ChangeTitleMenu_2               matlab.ui.container.Menu
        ChangeXLabelMenu_2              matlab.ui.container.Menu
        ChangeYLabelMenu_2              matlab.ui.container.Menu
        ChangeXlimMenu_2                matlab.ui.container.Menu
        ChangeYlimMenu_2                matlab.ui.container.Menu
        ChangeLegendMenu_2              matlab.ui.container.Menu
        ChangeFontsizeMenu_2            matlab.ui.container.Menu
        ContextMenu3_3                  matlab.ui.container.ContextMenu
        ChangeTitleMenu_3               matlab.ui.container.Menu
        ChangeXLabelMenu_3              matlab.ui.container.Menu
        ChangeYLabelMenu_3              matlab.ui.container.Menu
        ChangeXlimMenu_3                matlab.ui.container.Menu
        ChangeYlimMenu_3                matlab.ui.container.Menu
        ChangeLegendMenu_3              matlab.ui.container.Menu
        ChangeFontsizeMenu_3            matlab.ui.container.Menu
    end

    
    properties (Access = public)
        Mainapp = []; % Description
        numCluster = [];
        rgbMatrix = [];
        gwfparams = [];
        TwoORThreeD = "TwoD"; % standard 2d plots when possible
        AverageWaveforms = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            
            movegui(app.ContinousKilosortSpikeAnalysisUIFigure, 'center');
            
            app.Mainapp = Mainapp;
            
            [app] = Spike_Module_Set_Up_Spike_Analysis_Windows(app,[],"Non");
       
            [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],"Kilosort",app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.Mainapp.Data.Spikes.Waveforms);
            
            [~,app.Mainapp.CurrentPlotData] = Continous_Kilosort_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,PlotInfo,SpikePositions,SpikeAmps,SpikeTimes,Waveforms,WaveformChannel,CluterPositions,app.UIAxes,app.TypeofAnalysisDropDown.Value,app.TextArea,app.EventstoshowDropDown.Value,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData);
           
        end

        % Value changed function: ClustertoshowDropDown
        function ClustertoshowDropDownValueChanged(app, event)
            % delte saved unit specific data if no unit selected
            if strcmp(app.ClustertoshowDropDown.Value,"All") || strcmp(app.ClustertoshowDropDown.Value,"Non")
                if isfield(app.Mainapp.CurrentPlotData,'MainRateUnitXData')
                    app.Mainapp.CurrentPlotData = rmfield(app.Mainapp.CurrentPlotData, {'MainRateUnitXData', 'MainRateUnitYData','MainRateUnitCData', 'MainRateUnitType', 'MainRateUnitXTicks'});
                end
                if isfield(app.Mainapp.CurrentPlotData,'MainUnitXData')
                    app.Mainapp.CurrentPlotData = rmfield(app.Mainapp.CurrentPlotData, {'MainUnitXData', 'MainUnitYData','MainUnitCData', 'MainUnitType', 'MainUnitXTicks'});
                end
            end

            if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel") && isempty(app.AverageWaveforms)
                % For Kilosort we dont have channel information to extract from raw or
                % preprocessed data --> Therefor we take channel closest to position
                SpikePositions = (app.Mainapp.Data.Spikes.SpikePositions(:,2)./app.Mainapp.Data.Info.ChannelSpacing)+1;
                SpikePositions = round(SpikePositions);
                [app.AverageWaveforms,~] = Spikes_Module_Get_Waveforms(app.Mainapp.Data,app.Mainapp.Data.Spikes.SpikeTimes,SpikePositions,"AverageWaveforms");
            end
            
            if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel")
                [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],"Kilosort",app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.AverageWaveforms);
            else
                [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],"Kilosort",app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.Mainapp.Data.Spikes.Waveforms);
            end

           
            [~,app.Mainapp.CurrentPlotData] = Continous_Kilosort_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,PlotInfo,SpikePositions,SpikeAmps,SpikeTimes,Waveforms,WaveformChannel,CluterPositions,app.UIAxes,app.TypeofAnalysisDropDown.Value,app.TextArea,app.EventstoshowDropDown.Value,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData);
            
            %SpikeRate
            if ~strcmp(app.TypeofAnalysisDropDown.Value,"Spike Map") 
                [~,~] = Continous_Kilosort_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,PlotInfo,SpikePositions,SpikeAmps,SpikeTimes,Waveforms,WaveformChannel,CluterPositions,app.UIAxes,'SpikeRateBinSizeChange',app.TextArea,app.EventstoshowDropDown.Value,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData);
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: EventstoshowDropDown
        function EventstoshowDropDownValueChanged(app, event)
            [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],"Kilosort",app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.Mainapp.Data.Spikes.Waveforms);
            
            [~,app.Mainapp.CurrentPlotData] = Continous_Kilosort_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,PlotInfo,SpikePositions,SpikeAmps,SpikeTimes,Waveforms,WaveformChannel,CluterPositions,app.UIAxes,app.TypeofAnalysisDropDown.Value,app.TextArea,app.EventstoshowDropDown.Value,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData);
           
        end

        % Value changed function: SpikeRateNumBinsEditField
        function SpikeRateNumBinsEditFieldValueChanged(app, event)
            %% Prepare and Initiate Plots
            % Reset Spike Rate plot in order to show new bin size
            yyaxis(app.UIAxes_3, 'left');
            app.UIAxes_3.NextPlot = "replace"; 
            plot(app.UIAxes_3,0,0);
            app.UIAxes_3.NextPlot = "add";
            set(app.UIAxes_3, 'YDir','reverse');
            app.UIAxes_3.FontSize = 10;
            ylabel(app.UIAxes_3,"Spike Rate [Hz]")

            yyaxis(app.UIAxes_3, 'right');
            app.UIAxes_3.NextPlot = "replace"; 
            plot(app.UIAxes_3,0,0);
            app.UIAxes_3.NextPlot = "add";
            set(app.UIAxes_3, 'YDir','reverse');
            ylabel(app.UIAxes_3,"Spike Rate [Hz]")
            app.UIAxes_3.FontSize = 10;

            [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],"Kilosort",app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.Mainapp.Data.Spikes.Waveforms);
            
            [~,app.Mainapp.CurrentPlotData] = Continous_Kilosort_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,PlotInfo,SpikePositions,SpikeAmps,SpikeTimes,Waveforms,WaveformChannel,CluterPositions,app.UIAxes,'SpikeRateBinSizeChange',app.TextArea,app.EventstoshowDropDown.Value,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData);
           
        end

        % Menu selected function: PNGMenu
        function PNGMenuSelected(app, event)
            [file, path] = uiputfile('*.png', 'Save as');

            exportapp(app.ContinousKilosortSpikeAnalysisUIFigure,fullfile(path,file)) 
        end

        % Menu selected function: JPGMenu
        function JPGMenuSelected(app, event)
            [file, path] = uiputfile('*.jpg', 'Save as');

            exportapp(app.ContinousKilosortSpikeAnalysisUIFigure,fullfile(path,file)) 
        end

        % Menu selected function: ChangeTitleMenu
        function ChangeTitleMenuSelected(app, event)
            Figure_Property_Change_Window(app.UIAxes,'title');
        end

        % Menu selected function: ChangeXLabelMenu
        function ChangeXLabelMenuSelected(app, event)
            Figure_Property_Change_Window(app.UIAxes,'xlabel');
        end

        % Menu selected function: ChangeYLabelMenu
        function ChangeYLabelMenuSelected(app, event)
            Figure_Property_Change_Window(app.UIAxes,'ylabel');
        end

        % Menu selected function: ChangeXlimMenu
        function ChangeXlimMenuSelected(app, event)
            Figure_Property_Change_Window(app.UIAxes,'xlim');
        end

        % Menu selected function: ChangeYlimMenu
        function ChangeYlimMenuSelected(app, event)
            Figure_Property_Change_Window(app.UIAxes,'ylim');
        end

        % Menu selected function: ChangeLegendMenu
        function ChangeLegendMenuSelected(app, event)
            Figure_Property_Change_Window(app.UIAxes,'legend');
        end

        % Menu selected function: ChangeFontsizeMenu
        function ChangeFontsizeMenuSelected(app, event)
            
        end

        % Menu selected function: ChangeTitleMenu_2
        function ChangeTitleMenu_2Selected(app, event)
            Figure_Property_Change_Window(app.UIAxes_3,'title');
        end

        % Menu selected function: ChangeXLabelMenu_2
        function ChangeXLabelMenu_2Selected(app, event)
            Figure_Property_Change_Window(app.UIAxes_3,'xlabel');
        end

        % Menu selected function: ChangeYLabelMenu_2
        function ChangeYLabelMenu_2Selected(app, event)
            Figure_Property_Change_Window(app.UIAxes_3,'ylabel');
        end

        % Menu selected function: ChangeXlimMenu_2
        function ChangeXlimMenu_2Selected(app, event)
            Figure_Property_Change_Window(app.UIAxes_3,'xlim');
        end

        % Menu selected function: ChangeYlimMenu_2
        function ChangeYlimMenu_2Selected(app, event)
            Figure_Property_Change_Window(app.UIAxes_3,'ylim');
        end

        % Menu selected function: ChangeLegendMenu_2
        function ChangeLegendMenu_2Selected(app, event)
            Figure_Property_Change_Window(app.UIAxes_3,'legend');
        end

        % Menu selected function: ChangeFontsizeMenu_2
        function ChangeFontsizeMenu_2Selected(app, event)
            
        end

        % Menu selected function: ChangeTitleMenu_3
        function ChangeTitleMenu_3Selected(app, event)
            Figure_Property_Change_Window(app.UIAxes_5,'title');
        end

        % Menu selected function: ChangeXLabelMenu_3
        function ChangeXLabelMenu_3Selected(app, event)
            Figure_Property_Change_Window(app.UIAxes_5,'xlabel');
        end

        % Menu selected function: ChangeYLabelMenu_3
        function ChangeYLabelMenu_3Selected(app, event)
            Figure_Property_Change_Window(app.UIAxes_5,'ylabel');
        end

        % Menu selected function: ChangeXlimMenu_3
        function ChangeXlimMenu_3Selected(app, event)
            Figure_Property_Change_Window(app.UIAxes_5,'xlim');
        end

        % Menu selected function: ChangeYlimMenu_3
        function ChangeYlimMenu_3Selected(app, event)
            Figure_Property_Change_Window(app.UIAxes_5,'ylim');
        end

        % Menu selected function: ChangeLegendMenu_3
        function ChangeLegendMenu_3Selected(app, event)
            Figure_Property_Change_Window(app.UIAxes_5,'legend');
        end

        % Menu selected function: ChangeFontsizeMenu_3
        function ChangeFontsizeMenu_3Selected(app, event)
            
        end

        % Value changed function: TypeofAnalysisDropDown
        function TypeofAnalysisDropDownValueChanged(app, event)
            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";

            if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel") && isempty(app.AverageWaveforms)
                %% Data needs to be high pass filtered! Otherwise waveforms are weird. Recommended is also grand average
                % Detect high pass filter
                HigPassFiltered = 1;
                
                if isfield(app.Mainapp.Data,'Preprocessed') 
                    if isfield(app.Mainapp.Data.Info,'FilterMethod') 
                        if ~strcmp(app.Mainapp.Data.Info.FilterMethod,'High-Pass')
                            msgbox("Warning: No High Pass filtered data found. This will screw with scale and amplitude of waveforms. Data is temporarily high pass filtered for waveform extraction!");
                            HigPassFiltered = 0;
                        end
                    else
                        msgbox("Warning: No High Pass filtered data found. This will screw with scale and amplitude of waveforms. Data is temporarily high pass filtered for waveform extraction!");
                        HigPassFiltered = 0;
                    end
                else
                    msgbox("Warning: No High Pass filtered data found. This will screw with scale and amplitude of waveforms. Data is temporarily high pass filtered for waveform extraction!");
                    HigPassFiltered = 0;
                end
                
                %%% if it has to be high pass filtered --> save as TempData, extract
                %%% waveforms and delete temp variable
                if HigPassFiltered == 0
                    PreproInfo = [];
                    PreprocessingSteps = [];
                
                    Methods = ["Filter"];
                    [PreproInfo,PreprocessingSteps,~] = Preprocess_Module_Construct_Pipeline(Methods,PreproInfo,PreprocessingSteps,0,"High-Pass","Butterworth IR","300","Zero-phase forward and reverse","3",[],app.Mainapp.Data.Info.NativeSamplingRate);
                        
                    if isfield(PreproInfo,'ChannelDeletion')
                        ChannelDeletion = PreproInfo.ChannelDeletion;
                    else
                        ChannelDeletion = [];
                    end
                    
                    TextArea = [];
                    
                    [TempData,PreproInfo,TextArea] = Preprocess_Module_Delete_Old_Settings(app.Mainapp.Data,PreproInfo,PreprocessingSteps,ChannelDeletion,TextArea);
                    
                    [TempData] = Preprocess_Module_Apply_Pipeline (TempData,TempData.Info.NativeSamplingRate,PreprocessingSteps,0,PreproInfo,ChannelDeletion,TextArea); 
                    %% Now extract Waveforms
                    % For Kilosort we dont have channel information to extract from raw or
                    % preprocessed data --> Therefor we take channel closest to position
                    SpikePositions = (TempData.Spikes.SpikePositions(:,2)./TempData.Info.ChannelSpacing)+1;
                    SpikePositions = round(SpikePositions);
                    [app.AverageWaveforms,~] = Spikes_Module_Get_Waveforms(TempData,TempData.Spikes.SpikeTimes,SpikePositions,"AverageWaveforms");
                    clear TempData;
                else % If high pass was already applied
                    % For Kilosort we dont have channel information to extract from raw or
                    % preprocessed data --> Therefor we take channel closest to position
                    SpikePositions = (app.Mainapp.Data.Spikes.SpikePositions(:,2)./app.Mainapp.Data.Info.ChannelSpacing)+1;
                    SpikePositions = round(SpikePositions);
                    [app.AverageWaveforms,~] = Spikes_Module_Get_Waveforms(app.Mainapp.Data,app.Mainapp.Data.Spikes.SpikeTimes,SpikePositions,"AverageWaveforms");
                end
            end
            
            if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel")
                [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],"Kilosort",app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.AverageWaveforms);
            else
                [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],"Kilosort",app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.Mainapp.Data.Spikes.Waveforms);
            end

            [TempData,app.Mainapp.CurrentPlotData] = Continous_Kilosort_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,PlotInfo,SpikePositions,SpikeAmps,SpikeTimes,Waveforms,WaveformChannel,CluterPositions,app.UIAxes,app.TypeofAnalysisDropDown.Value,app.TextArea,app.EventstoshowDropDown.Value,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData);
           
            %% Manage which GUI elements are active
            if strcmp(app.TypeofAnalysisDropDown.Value,"Spike Map")
                app.EventstoshowDropDown.Enable = "on";
                app.TimeWindowSpiketriggredLFPEditField.Enable = "off";
                app.WaveformSelectionforPlottingEditField.Enable = "off";
            elseif strcmp(app.TypeofAnalysisDropDown.Value,"Spike Amplitude Density Along Depth")
                app.EventstoshowDropDown.Enable = "off";
                app.TimeWindowSpiketriggredLFPEditField.Enable = "off";
                app.WaveformSelectionforPlottingEditField.Enable = "off";
            elseif strcmp(app.TypeofAnalysisDropDown.Value,"Cumulative Spike Amplitude Density Along Depth")
                app.EventstoshowDropDown.Enable = "off";
                app.TimeWindowSpiketriggredLFPEditField.Enable = "off";
                app.WaveformSelectionforPlottingEditField.Enable = "off";
            elseif strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel")
                app.EventstoshowDropDown.Enable = "off";
                app.TimeWindowSpiketriggredLFPEditField.Enable = "off";
                app.WaveformSelectionforPlottingEditField.Enable = "on";
            elseif strcmp(app.TypeofAnalysisDropDown.Value,"Waveforms from Raw Data")
                app.EventstoshowDropDown.Enable = "off";
                app.TimeWindowSpiketriggredLFPEditField.Enable = "off";
                app.WaveformSelectionforPlottingEditField.Enable = "on";
            elseif strcmp(app.TypeofAnalysisDropDown.Value,"Waveforms Templates")
                app.EventstoshowDropDown.Enable = "off";
                app.TimeWindowSpiketriggredLFPEditField.Enable = "off";
                app.WaveformSelectionforPlottingEditField.Enable = "on";
            elseif strcmp(app.TypeofAnalysisDropDown.Value,"Template from Max Amplitude Channel")
                app.EventstoshowDropDown.Enable = "off";
                app.TimeWindowSpiketriggredLFPEditField.Enable = "off";
                app.WaveformSelectionforPlottingEditField.Enable = "on";
            elseif strcmp(app.TypeofAnalysisDropDown.Value,"Spike Triggered LFP")
                app.EventstoshowDropDown.Enable = "off";
                app.TimeWindowSpiketriggredLFPEditField.Enable = "on";
                app.WaveformSelectionforPlottingEditField.Enable = "off";
            end

            if strcmp(app.TypeofAnalysisDropDown.Value,"Spike Triggered LFP")
                if ~isempty(TempData)
                    app.Mainapp.Data = TempData;
                    PreviousChannelDeletetion = 0;
                    % If Channel were deleted in previous preprocessing flag this
                    % to reset main window parameter after + only allow for prepro
                    % view if prepro was generated
                    if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                        PreviousChannelDeletetion = 1;
                    end
                    %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                    Utility_Show_Info_Loaded_Data(app.Mainapp);
                    %% Wrap up preprocessing
                    [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                    if app.Mainapp.PreprocDataPlotCheckBox.Value == 1
                        if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                            TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                            % Calculate the absolute differences
                            differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                            
                            % Find the index of the minimum difference
                            [~, app.Mainapp.CurrentTimePoints] = min(differences);
                        end
                    end
                    %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                    % Plot functions are fully autonomous without needed the app
                    % object. It is only needed to get the necessary parameter.
                    % Both is combined in one function for convenience.
                    %input 2: 1 if plot time, 0 if no time plot necessary
                    %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                    %input 4: Whether Data plot should run in a movie or not
                    Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
                end
            else
                app.Mainapp.Data = TempData;
            end
        end

        % Value changed function: WaveformSelectionforPlottingEditField
        function WaveformSelectionforPlottingEditFieldValueChanged(app, event)
            if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel") && isempty(app.AverageWaveforms)
                % For Kilosort we dont have channel information to extract from raw or
                % preprocessed data --> Therefor we take channel closest to position
                SpikePositions = (app.Mainapp.Data.Spikes.SpikePositions(:,2)./app.Mainapp.Data.Info.ChannelSpacing)+1;
                SpikePositions = round(SpikePositions);
                [app.AverageWaveforms,~] = Spikes_Module_Get_Waveforms(app.Mainapp.Data,app.Mainapp.Data.Spikes.SpikeTimes,SpikePositions,"AverageWaveforms");
            end
            
            if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel")
                [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],"Kilosort",app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.AverageWaveforms);
            else
                [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],"Kilosort",app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.Mainapp.Data.Spikes.Waveforms);
            end

            if ~strcmp(app.TypeofAnalysisDropDown.Value,"Spike Triggered LFP")
                [~,app.Mainapp.CurrentPlotData] = Continous_Kilosort_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,PlotInfo,SpikePositions,SpikeAmps,SpikeTimes,Waveforms,WaveformChannel,CluterPositions,app.UIAxes,app.TypeofAnalysisDropDown.Value,app.TextArea,app.EventstoshowDropDown.Value,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData);
            end
        end

        % Value changed function: ChannelSelectionforPlottingEditField
        function ChannelSelectionforPlottingEditFieldValueChanged(app, event)
              [app.ChannelSelectionforPlottingEditField.Value] = Utility_SimpleCheckInputs(app.ChannelSelectionforPlottingEditField.Value,"Two",strcat('1,',num2str(size(app.Mainapp.Data.Raw,1))),1,0);
              
            if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel") && isempty(app.AverageWaveforms)
                % For Kilosort we dont have channel information to extract from raw or
                % preprocessed data --> Therefor we take channel closest to position
                SpikePositions = (app.Mainapp.Data.Spikes.SpikePositions(:,2)./app.Mainapp.Data.Info.ChannelSpacing)+1;
                SpikePositions = round(SpikePositions);
                [app.AverageWaveforms,~] = Spikes_Module_Get_Waveforms(app.Mainapp.Data,app.Mainapp.Data.Spikes.SpikeTimes,SpikePositions,"AverageWaveforms");
            end
            
            if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel")
                [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],"Kilosort",app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.AverageWaveforms);
            else
                [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],"Kilosort",app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.Mainapp.Data.Spikes.Waveforms);
            end

              if ~strcmp(app.TypeofAnalysisDropDown.Value,"Spike Map") 
                  [~,~] = Continous_Kilosort_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,PlotInfo,SpikePositions,SpikeAmps,SpikeTimes,Waveforms,WaveformChannel,CluterPositions,app.UIAxes,"SpikeRateBinSizeChange",app.TextArea,app.EventstoshowDropDown.Value,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData);
              end

              [TempData,app.Mainapp.CurrentPlotData] = Continous_Kilosort_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,PlotInfo,SpikePositions,SpikeAmps,SpikeTimes,Waveforms,WaveformChannel,CluterPositions,app.UIAxes,app.TypeofAnalysisDropDown.Value,app.TextArea,app.EventstoshowDropDown.Value,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData);
           
              if strcmp(app.TypeofAnalysisDropDown.Value,"Spike Triggered LFP")
                if ~isempty(TempData)
                    app.Mainapp.Data = TempData;
                    PreviousChannelDeletetion = 0;
                    % If Channel were deleted in previous preprocessing flag this
                    % to reset main window parameter after + only allow for prepro
                    % view if prepro was generated
                    if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                        PreviousChannelDeletetion = 1;
                    end
                    %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                    Utility_Show_Info_Loaded_Data(app.Mainapp);
                    %% Wrap up preprocessing
                    [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                    if app.Mainapp.PreprocDataPlotCheckBox.Value == 1
                        if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                            TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                            % Calculate the absolute differences
                            differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                            
                            % Find the index of the minimum difference
                            [~, app.Mainapp.CurrentTimePoints] = min(differences);
                        end
                    end
                    %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                    % Plot functions are fully autonomous without needed the app
                    % object. It is only needed to get the necessary parameter.
                    % Both is combined in one function for convenience.
                    %input 2: 1 if plot time, 0 if no time plot necessary
                    %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                    %input 4: Whether Data plot should run in a movie or not
                    Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
                end
             else
                app.Mainapp.Data = TempData;
             end
        end

        % Value changed function: TimeWindowSpiketriggredLFPEditField
        function TimeWindowSpiketriggredLFPEditFieldValueChanged(app, event)
            [app.TimeWindowSpiketriggredLFPEditField.Value] = Utility_SimpleCheckInputs(app.TimeWindowSpiketriggredLFPEditField.Value,"Two",strcat('-0.005,0.2'),0,0);

            [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],"Kilosort",app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.Mainapp.Data.Spikes.Waveforms);
            
            [TempData,app.Mainapp.CurrentPlotData] = Continous_Kilosort_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,PlotInfo,SpikePositions,SpikeAmps,SpikeTimes,Waveforms,WaveformChannel,CluterPositions,app.UIAxes,app.TypeofAnalysisDropDown.Value,app.TextArea,app.EventstoshowDropDown.Value,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData);
           
            if strcmp(app.TypeofAnalysisDropDown.Value,"Spike Triggered LFP")
                if ~isempty(TempData)
                    app.Mainapp.Data = TempData;
                    PreviousChannelDeletetion = 0;
                    % If Channel were deleted in previous preprocessing flag this
                    % to reset main window parameter after + only allow for prepro
                    % view if prepro was generated
                    if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                        PreviousChannelDeletetion = 1;
                    end
                    %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                    Utility_Show_Info_Loaded_Data(app.Mainapp);
                    %% Wrap up preprocessing
                    [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                    if app.Mainapp.PreprocDataPlotCheckBox.Value == 1
                        if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                            TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                            % Calculate the absolute differences
                            differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                            
                            % Find the index of the minimum difference
                            [~, app.Mainapp.CurrentTimePoints] = min(differences);
                        end
                    end
                    %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                    % Plot functions are fully autonomous without needed the app
                    % object. It is only needed to get the necessary parameter.
                    % Both is combined in one function for convenience.
                    %input 2: 1 if plot time, 0 if no time plot necessary
                    %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                    %input 4: Whether Data plot should run in a movie or not
                    Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
                end
             else
                app.Mainapp.Data = TempData;
             end
        end

        % Menu selected function: Show2DimensionalPlotsMenu
        function Show2DimensionalPlotsMenuSelected(app, event)
            app.TwoORThreeD = "TwoD";

            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";
            set(app.UIAxes, 'YDir','reverse');
            set(app.UIAxes,'xticklabel',{[]});

            if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel") && isempty(app.AverageWaveforms)
                % For Kilosort we dont have channel information to extract from raw or
                % preprocessed data --> Therefor we take channel closest to position
                SpikePositions = (app.Mainapp.Data.Spikes.SpikePositions(:,2)./app.Mainapp.Data.Info.ChannelSpacing)+1;
                SpikePositions = round(SpikePositions);
                [app.AverageWaveforms,~] = Spikes_Module_Get_Waveforms(app.Mainapp.Data,app.Mainapp.Data.Spikes.SpikeTimes,SpikePositions,"AverageWaveforms");
            end
            
            if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel")
                [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],"Kilosort",app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.AverageWaveforms);
            else
                [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],"Kilosort",app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.Mainapp.Data.Spikes.Waveforms);
            end
            
            [TempData,app.Mainapp.CurrentPlotData] = Continous_Kilosort_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,PlotInfo,SpikePositions,SpikeAmps,SpikeTimes,Waveforms,WaveformChannel,CluterPositions,app.UIAxes,app.TypeofAnalysisDropDown.Value,app.TextArea,app.EventstoshowDropDown.Value,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData);
           
            if strcmp(app.TypeofAnalysisDropDown.Value,"Spike Triggered LFP")
                if ~isempty(TempData)
                    app.Mainapp.Data = TempData;
                    PreviousChannelDeletetion = 0;
                    % If Channel were deleted in previous preprocessing flag this
                    % to reset main window parameter after + only allow for prepro
                    % view if prepro was generated
                    if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                        PreviousChannelDeletetion = 1;
                    end
                    %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                    Utility_Show_Info_Loaded_Data(app.Mainapp);
                    %% Wrap up preprocessing
                    [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                    if app.Mainapp.PreprocDataPlotCheckBox.Value == 1
                        if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                            TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                            % Calculate the absolute differences
                            differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                            
                            % Find the index of the minimum difference
                            [~, app.Mainapp.CurrentTimePoints] = min(differences);
                        end
                    end
                    %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                    % Plot functions are fully autonomous without needed the app
                    % object. It is only needed to get the necessary parameter.
                    % Both is combined in one function for convenience.
                    %input 2: 1 if plot time, 0 if no time plot necessary
                    %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                    %input 4: Whether Data plot should run in a movie or not
                    Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
                end
             else
                app.Mainapp.Data = TempData;
             end
        end

        % Menu selected function: SpikeRateMenu
        function SpikeRateMenuSelected(app, event)
            
        end

        % Menu selected function: MainPlotMenu
        function MainPlotMenuSelected(app, event)
            
        end

        % Menu selected function: OverTimeMenu
        function OverTimeMenuSelected(app, event)
           
        end

        % Menu selected function: OverChannelMenu
        function OverChannelMenuSelected(app, event)
           
        end

        % Menu selected function: matMenu_2
        function matMenu_2Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Time(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continous Kilosort Spike Rate Unit ",app.ClustertoshowDropDown.Value));
        end

        % Menu selected function: txtMenu_3
        function txtMenu_3Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Time(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continous Kilosort Spike Rate Unit ",app.ClustertoshowDropDown.Value));
        end

        % Menu selected function: csvMenu_3
        function csvMenu_3Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Time(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".csv",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continous Kilosort Spike Rate Unit ",app.ClustertoshowDropDown.Value));
        end

        % Menu selected function: matMenu_3
        function matMenu_3Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Time(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continous Kilosort Spikes ",app.TypeofAnalysisDropDown.Value));
        end

        % Menu selected function: txtMenu_4
        function txtMenu_4Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Time(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continous Kilosort Spikes ",app.TypeofAnalysisDropDown.Value));
        end

        % Menu selected function: csvMenu_4
        function csvMenu_4Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Time(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".csv",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continous Kilosort Spikes ",app.TypeofAnalysisDropDown.Value));
        end

        % Menu selected function: matMenu_4
        function matMenu_4Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Time(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continous Kilosort Unit Spikes ",app.TypeofAnalysisDropDown.Value));
        end

        % Menu selected function: txtMenu_5
        function txtMenu_5Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Time(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continous Kilosort Unit Spikes ",app.TypeofAnalysisDropDown.Value));
        end

        % Menu selected function: csvMenu_5
        function csvMenu_5Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Time(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".csv",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continous Kilosort Unit Spikes ",app.TypeofAnalysisDropDown.Value));
        end

        % Menu selected function: matMenu_5
        function matMenu_5Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Time(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continous Kilosort All Spikes Spike Rate over Time ",app.TypeofAnalysisDropDown.Value));
        end

        % Menu selected function: txtMenu_6
        function txtMenu_6Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Time(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continous Kilosort All Spikes Spike Rate over Time ",app.TypeofAnalysisDropDown.Value));
        end

        % Menu selected function: csvMenu_6
        function csvMenu_6Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Time(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".csv",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continous Kilosort All Spikes Spike Rate over Time ",app.TypeofAnalysisDropDown.Value));
        end

        % Menu selected function: matMenu_6
        function matMenu_6Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Time(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continous Kilosort All Spikes Spike Rate over Channel ",app.TypeofAnalysisDropDown.Value));
        end

        % Menu selected function: txtMenu_7
        function txtMenu_7Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Time(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continous Kilosort All Spikes Spike Rate over Channel ",app.TypeofAnalysisDropDown.Value));
        end

        % Menu selected function: csvMenu_7
        function csvMenu_7Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Time(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".csv",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continous Kilosort All Spikes Spike Rate over Channel ",app.TypeofAnalysisDropDown.Value));
        end

        % Menu selected function: Show3DimensionalPlotsMenu
        function Show3DimensionalPlotsMenuSelected(app, event)
            app.TwoORThreeD = "ThreeD";

            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";
            set(app.UIAxes, 'YDir','reverse');
            set(app.UIAxes,'xticklabel',{[]});

            if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel") && isempty(app.AverageWaveforms)
                % For Kilosort we dont have channel information to extract from raw or
                % preprocessed data --> Therefor we take channel closest to position
                SpikePositions = (app.Mainapp.Data.Spikes.SpikePositions(:,2)./app.Mainapp.Data.Info.ChannelSpacing)+1;
                SpikePositions = round(SpikePositions);
                [app.AverageWaveforms,~] = Spikes_Module_Get_Waveforms(app.Mainapp.Data,app.Mainapp.Data.Spikes.SpikeTimes,SpikePositions,"AverageWaveforms");
            end
            
            if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel")
                [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],"Kilosort",app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.AverageWaveforms);
            else
                [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],"Kilosort",app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.Mainapp.Data.Spikes.Waveforms);
            end
            
            [TempData,app.Mainapp.CurrentPlotData] = Continous_Kilosort_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,PlotInfo,SpikePositions,SpikeAmps,SpikeTimes,Waveforms,WaveformChannel,CluterPositions,app.UIAxes,app.TypeofAnalysisDropDown.Value,app.TextArea,app.EventstoshowDropDown.Value,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData);
           
            if strcmp(app.TypeofAnalysisDropDown.Value,"Spike Triggered LFP")
                if ~isempty(TempData)
                    app.Mainapp.Data = TempData;
                    PreviousChannelDeletetion = 0;
                    % If Channel were deleted in previous preprocessing flag this
                    % to reset main window parameter after + only allow for prepro
                    % view if prepro was generated
                    if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                        PreviousChannelDeletetion = 1;
                    end
                    %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                    Utility_Show_Info_Loaded_Data(app.Mainapp);
                    %% Wrap up preprocessing
                    [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                    if app.Mainapp.PreprocDataPlotCheckBox.Value == 1
                        if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                            TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                            % Calculate the absolute differences
                            differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                            
                            % Find the index of the minimum difference
                            [~, app.Mainapp.CurrentTimePoints] = min(differences);
                        end
                    end
                    %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                    % Plot functions are fully autonomous without needed the app
                    % object. It is only needed to get the necessary parameter.
                    % Both is combined in one function for convenience.
                    %input 2: 1 if plot time, 0 if no time plot necessary
                    %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                    %input 4: Whether Data plot should run in a movie or not
                    Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
                end
             else
                app.Mainapp.Data = TempData;
             end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ContinousKilosortSpikeAnalysisUIFigure = uifigure('Visible', 'off');
            app.ContinousKilosortSpikeAnalysisUIFigure.Name = 'Continous Kilosort Spike Analysis';
            app.ContinousKilosortSpikeAnalysisUIFigure.Icon = 'Logo.png';
            app.SnapshotMenu = uimenu(app.ContinousKilosortSpikeAnalysisUIFigure);
            app.SnapshotMenu.Text = '   Snapshot   ';
            app.SaveSnapshotofWindowMenu = uimenu(app.SnapshotMenu);
            app.SaveSnapshotofWindowMenu.Text = 'Save Snapshot of Window';
            app.PNGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.PNGMenu.MenuSelectedFcn = createCallbackFcn(app, @PNGMenuSelected, true);
            app.PNGMenu.Text = 'PNG';
            app.JPGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.JPGMenu.MenuSelectedFcn = createCallbackFcn(app, @JPGMenuSelected, true);
            app.JPGMenu.Text = 'JPG';
            app.PlotMenu = uimenu(app.ContinousKilosortSpikeAnalysisUIFigure);
            app.PlotMenu.Text = '   Plot   ';
            app.Show2DimensionalPlotsMenu = uimenu(app.PlotMenu);
            app.Show2DimensionalPlotsMenu.MenuSelectedFcn = createCallbackFcn(app, @Show2DimensionalPlotsMenuSelected, true);
            app.Show2DimensionalPlotsMenu.Text = 'Show 2 Dimensional Plots';
            app.Show3DimensionalPlotsMenu = uimenu(app.PlotMenu);
            app.Show3DimensionalPlotsMenu.MenuSelectedFcn = createCallbackFcn(app, @Show3DimensionalPlotsMenuSelected, true);
            app.Show3DimensionalPlotsMenu.Text = 'Show 3 Dimensional Plots';
            app.ExportDataasMenu = uimenu(app.ContinousKilosortSpikeAnalysisUIFigure);
            app.ExportDataasMenu.Text = '   Export Data as   ';
            app.SpikeRateMenu = uimenu(app.ExportDataasMenu);
            app.SpikeRateMenu.MenuSelectedFcn = createCallbackFcn(app, @SpikeRateMenuSelected, true);
            app.SpikeRateMenu.Text = 'Spike Rate';
            app.AllSpikesMenu = uimenu(app.SpikeRateMenu);
            app.AllSpikesMenu.Text = 'All Spikes';
            app.OverTimeMenu = uimenu(app.AllSpikesMenu);
            app.OverTimeMenu.MenuSelectedFcn = createCallbackFcn(app, @OverTimeMenuSelected, true);
            app.OverTimeMenu.Text = 'Over Time';
            app.matMenu_5 = uimenu(app.OverTimeMenu);
            app.matMenu_5.MenuSelectedFcn = createCallbackFcn(app, @matMenu_5Selected, true);
            app.matMenu_5.Text = '.mat';
            app.txtMenu_6 = uimenu(app.OverTimeMenu);
            app.txtMenu_6.MenuSelectedFcn = createCallbackFcn(app, @txtMenu_6Selected, true);
            app.txtMenu_6.Text = '.txt';
            app.csvMenu_6 = uimenu(app.OverTimeMenu);
            app.csvMenu_6.MenuSelectedFcn = createCallbackFcn(app, @csvMenu_6Selected, true);
            app.csvMenu_6.Text = '.csv';
            app.OverChannelMenu = uimenu(app.AllSpikesMenu);
            app.OverChannelMenu.MenuSelectedFcn = createCallbackFcn(app, @OverChannelMenuSelected, true);
            app.OverChannelMenu.Text = 'Over Channel';
            app.matMenu_6 = uimenu(app.OverChannelMenu);
            app.matMenu_6.MenuSelectedFcn = createCallbackFcn(app, @matMenu_6Selected, true);
            app.matMenu_6.Text = '.mat';
            app.txtMenu_7 = uimenu(app.OverChannelMenu);
            app.txtMenu_7.MenuSelectedFcn = createCallbackFcn(app, @txtMenu_7Selected, true);
            app.txtMenu_7.Text = '.txt';
            app.csvMenu_7 = uimenu(app.OverChannelMenu);
            app.csvMenu_7.MenuSelectedFcn = createCallbackFcn(app, @csvMenu_7Selected, true);
            app.csvMenu_7.Text = '.csv';
            app.SelectedUnitMenu = uimenu(app.SpikeRateMenu);
            app.SelectedUnitMenu.Text = 'Selected Unit';
            app.matMenu_2 = uimenu(app.SelectedUnitMenu);
            app.matMenu_2.MenuSelectedFcn = createCallbackFcn(app, @matMenu_2Selected, true);
            app.matMenu_2.Text = '.mat';
            app.txtMenu_3 = uimenu(app.SelectedUnitMenu);
            app.txtMenu_3.MenuSelectedFcn = createCallbackFcn(app, @txtMenu_3Selected, true);
            app.txtMenu_3.Text = '.txt';
            app.csvMenu_3 = uimenu(app.SelectedUnitMenu);
            app.csvMenu_3.MenuSelectedFcn = createCallbackFcn(app, @csvMenu_3Selected, true);
            app.csvMenu_3.Text = '.csv';
            app.MainPlotMenu = uimenu(app.ExportDataasMenu);
            app.MainPlotMenu.MenuSelectedFcn = createCallbackFcn(app, @MainPlotMenuSelected, true);
            app.MainPlotMenu.Text = 'Main Plot';
            app.AllSpikesMenu_2 = uimenu(app.MainPlotMenu);
            app.AllSpikesMenu_2.Text = 'All Spikes';
            app.matMenu_3 = uimenu(app.AllSpikesMenu_2);
            app.matMenu_3.MenuSelectedFcn = createCallbackFcn(app, @matMenu_3Selected, true);
            app.matMenu_3.Text = '.mat';
            app.txtMenu_4 = uimenu(app.AllSpikesMenu_2);
            app.txtMenu_4.MenuSelectedFcn = createCallbackFcn(app, @txtMenu_4Selected, true);
            app.txtMenu_4.Text = '.txt';
            app.csvMenu_4 = uimenu(app.AllSpikesMenu_2);
            app.csvMenu_4.MenuSelectedFcn = createCallbackFcn(app, @csvMenu_4Selected, true);
            app.csvMenu_4.Text = '.csv';
            app.SelectedUnitMenu_2 = uimenu(app.MainPlotMenu);
            app.SelectedUnitMenu_2.Text = 'Selected Unit';
            app.matMenu_4 = uimenu(app.SelectedUnitMenu_2);
            app.matMenu_4.MenuSelectedFcn = createCallbackFcn(app, @matMenu_4Selected, true);
            app.matMenu_4.Text = '.mat';
            app.txtMenu_5 = uimenu(app.SelectedUnitMenu_2);
            app.txtMenu_5.MenuSelectedFcn = createCallbackFcn(app, @txtMenu_5Selected, true);
            app.txtMenu_5.Text = '.txt';
            app.csvMenu_5 = uimenu(app.SelectedUnitMenu_2);
            app.csvMenu_5.MenuSelectedFcn = createCallbackFcn(app, @csvMenu_5Selected, true);
            app.csvMenu_5.Text = '.csv';
            app.UIAxes_3 = uiaxes(app.ContinousKilosortSpikeAnalysisUIFigure);
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes = uiaxes(app.ContinousKilosortSpikeAnalysisUIFigure);
            title(app.UIAxes, 'Title')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes_5 = uiaxes(app.ContinousKilosortSpikeAnalysisUIFigure);
            xlabel(app.UIAxes_5, 'X')
            zlabel(app.UIAxes_5, 'Z')
            app.Panel = uipanel(app.ContinousKilosortSpikeAnalysisUIFigure);
            app.SpikeRateNumBinsEditFieldLabel = uilabel(app.Panel);
            app.SpikeRateNumBinsEditFieldLabel.Text = {'Spike Rate'; 'Num Bins'};
            app.SpikeRateNumBinsEditField = uieditfield(app.Panel, 'text');
            app.SpikeRateNumBinsEditField.ValueChangedFcn = createCallbackFcn(app, @SpikeRateNumBinsEditFieldValueChanged, true);
            app.SpikeRateNumBinsEditField.Value = '300';
            app.EventstoShowDropDownLabel = uilabel(app.Panel);
            app.EventstoShowDropDownLabel.Text = 'Events to Show';
            app.EventstoshowDropDown = uidropdown(app.Panel);
            app.EventstoshowDropDown.Items = {};
            app.EventstoshowDropDown.ValueChangedFcn = createCallbackFcn(app, @EventstoshowDropDownValueChanged, true);
            app.EventstoshowDropDown.Value = {};
            app.ClustertoShowDropDownLabel = uilabel(app.Panel);
            app.ClustertoShowDropDownLabel.Text = 'Cluster to Show';
            app.ClustertoshowDropDown = uidropdown(app.Panel);
            app.ClustertoshowDropDown.Items = {''};
            app.ClustertoshowDropDown.ValueChangedFcn = createCallbackFcn(app, @ClustertoshowDropDownValueChanged, true);
            app.ClustertoshowDropDown.Value = '';
            app.TextArea = uitextarea(app.Panel);
            app.TypeofAnalysisLabel = uilabel(app.Panel);
            app.TypeofAnalysisLabel.Text = 'Type of Analysis';
            app.TypeofAnalysisDropDown = uidropdown(app.Panel);
            app.TypeofAnalysisDropDown.Items = {'Spike Map', 'Spike Amplitude Density Along Depth', 'Cumulative Spike Amplitude Density Along Depth', 'Average Waveforms Across Channel', 'Waveforms from Raw Data', 'Waveforms Templates', 'Template from Max Amplitude Channel', 'Spike Triggered LFP'};
            app.TypeofAnalysisDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeofAnalysisDropDownValueChanged, true);
            app.TypeofAnalysisDropDown.Value = 'Spike Map';
            app.TimeWindowSpiketriggredLFPEditFieldLabel = uilabel(app.Panel);
            app.TimeWindowSpiketriggredLFPEditFieldLabel.Text = {'Time Window '; 'Spike triggred LFP'};
            app.TimeWindowSpiketriggredLFPEditField = uieditfield(app.Panel, 'text');
            app.TimeWindowSpiketriggredLFPEditField.ValueChangedFcn = createCallbackFcn(app, @TimeWindowSpiketriggredLFPEditFieldValueChanged, true);
            app.TimeWindowSpiketriggredLFPEditField.Value = '0.005,0.2';
            app.ChannelSelectionforPlottingEditFieldLabel = uilabel(app.Panel);
            app.ChannelSelectionforPlottingEditFieldLabel.Text = {'Channel Selection'; 'for Plotting'};
            app.ChannelSelectionforPlottingEditField = uieditfield(app.Panel, 'text');
            app.ChannelSelectionforPlottingEditField.ValueChangedFcn = createCallbackFcn(app, @ChannelSelectionforPlottingEditFieldValueChanged, true);
            app.KilosortAnalysisWindowLabel = uilabel(app.ContinousKilosortSpikeAnalysisUIFigure);
            app.KilosortAnalysisWindowLabel.Text = 'Kilosort Analysis Window';
            app.Image = uiimage(app.ContinousKilosortSpikeAnalysisUIFigure);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodToolboxLabel = uilabel(app.ContinousKilosortSpikeAnalysisUIFigure);
            app.NeuromodToolboxLabel.Text = 'Neuromod Toolbox';
            app.CloseButton = uibutton(app.ContinousKilosortSpikeAnalysisUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.Panel_2 = uipanel(app.ContinousKilosortSpikeAnalysisUIFigure);
            app.WaveformSelectionforPlottingEditFieldLabel = uilabel(app.Panel_2);
            app.WaveformSelectionforPlottingEditFieldLabel.Text = {'Waveform Selection'; 'for Plotting'};
            app.WaveformSelectionforPlottingEditField = uieditfield(app.Panel_2, 'text');
            app.WaveformSelectionforPlottingEditField.ValueChangedFcn = createCallbackFcn(app, @WaveformSelectionforPlottingEditFieldValueChanged, true);
            app.WaveformSelectionforPlottingEditField.Enable = 'off';
            app.WaveformSelectionforPlottingEditField.Value = '1,100';
            app.ContextMenu3 = uicontextmenu(app.ContinousKilosortSpikeAnalysisUIFigure);
            app.ChangeTitleMenu = uimenu(app.ContextMenu3);
            app.ChangeTitleMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeTitleMenuSelected, true);
            app.ChangeTitleMenu.Text = 'Change Title';
            app.ChangeXLabelMenu = uimenu(app.ContextMenu3);
            app.ChangeXLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeXLabelMenuSelected, true);
            app.ChangeXLabelMenu.Text = 'Change X Label';
            app.ChangeYLabelMenu = uimenu(app.ContextMenu3);
            app.ChangeYLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeYLabelMenuSelected, true);
            app.ChangeYLabelMenu.Text = 'Change Y Label';
            app.ChangeXlimMenu = uimenu(app.ContextMenu3);
            app.ChangeXlimMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeXlimMenuSelected, true);
            app.ChangeXlimMenu.Text = 'Change Xlim';
            app.ChangeYlimMenu = uimenu(app.ContextMenu3);
            app.ChangeYlimMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeYlimMenuSelected, true);
            app.ChangeYlimMenu.Text = 'Change Ylim';
            app.ChangeLegendMenu = uimenu(app.ContextMenu3);
            app.ChangeLegendMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeLegendMenuSelected, true);
            app.ChangeLegendMenu.Text = 'Change Legend';
            app.ChangeFontsizeMenu = uimenu(app.ContextMenu3);
            app.ChangeFontsizeMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeFontsizeMenuSelected, true);
            app.ChangeFontsizeMenu.Text = 'Change Fontsize';
            % Assign app.ContextMenu3
            app.UIAxes.ContextMenu = app.ContextMenu3;
            app.ContextMenu3_2 = uicontextmenu(app.ContinousKilosortSpikeAnalysisUIFigure);
            app.ChangeTitleMenu_2 = uimenu(app.ContextMenu3_2);
            app.ChangeTitleMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ChangeTitleMenu_2Selected, true);
            app.ChangeTitleMenu_2.Text = 'Change Title';
            app.ChangeXLabelMenu_2 = uimenu(app.ContextMenu3_2);
            app.ChangeXLabelMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ChangeXLabelMenu_2Selected, true);
            app.ChangeXLabelMenu_2.Text = 'Change X Label';
            app.ChangeYLabelMenu_2 = uimenu(app.ContextMenu3_2);
            app.ChangeYLabelMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ChangeYLabelMenu_2Selected, true);
            app.ChangeYLabelMenu_2.Text = 'Change Y Label';
            app.ChangeXlimMenu_2 = uimenu(app.ContextMenu3_2);
            app.ChangeXlimMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ChangeXlimMenu_2Selected, true);
            app.ChangeXlimMenu_2.Text = 'Change Xlim';
            app.ChangeYlimMenu_2 = uimenu(app.ContextMenu3_2);
            app.ChangeYlimMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ChangeYlimMenu_2Selected, true);
            app.ChangeYlimMenu_2.Text = 'Change Ylim';
            app.ChangeLegendMenu_2 = uimenu(app.ContextMenu3_2);
            app.ChangeLegendMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ChangeLegendMenu_2Selected, true);
            app.ChangeLegendMenu_2.Text = 'Change Legend';
            app.ChangeFontsizeMenu_2 = uimenu(app.ContextMenu3_2);
            app.ChangeFontsizeMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ChangeFontsizeMenu_2Selected, true);
            app.ChangeFontsizeMenu_2.Text = 'Change Fontsize';
            % Assign app.ContextMenu3_2
            app.UIAxes_3.ContextMenu = app.ContextMenu3_2;
            app.ContextMenu3_3 = uicontextmenu(app.ContinousKilosortSpikeAnalysisUIFigure);
            app.ChangeTitleMenu_3 = uimenu(app.ContextMenu3_3);
            app.ChangeTitleMenu_3.MenuSelectedFcn = createCallbackFcn(app, @ChangeTitleMenu_3Selected, true);
            app.ChangeTitleMenu_3.Text = 'Change Title';
            app.ChangeXLabelMenu_3 = uimenu(app.ContextMenu3_3);
            app.ChangeXLabelMenu_3.MenuSelectedFcn = createCallbackFcn(app, @ChangeXLabelMenu_3Selected, true);
            app.ChangeXLabelMenu_3.Text = 'Change X Label';
            app.ChangeYLabelMenu_3 = uimenu(app.ContextMenu3_3);
            app.ChangeYLabelMenu_3.MenuSelectedFcn = createCallbackFcn(app, @ChangeYLabelMenu_3Selected, true);
            app.ChangeYLabelMenu_3.Text = 'Change Y Label';
            app.ChangeXlimMenu_3 = uimenu(app.ContextMenu3_3);
            app.ChangeXlimMenu_3.MenuSelectedFcn = createCallbackFcn(app, @ChangeXlimMenu_3Selected, true);
            app.ChangeXlimMenu_3.Text = 'Change Xlim';
            app.ChangeYlimMenu_3 = uimenu(app.ContextMenu3_3);
            app.ChangeYlimMenu_3.MenuSelectedFcn = createCallbackFcn(app, @ChangeYlimMenu_3Selected, true);
            app.ChangeYlimMenu_3.Text = 'Change Ylim';
            app.ChangeLegendMenu_3 = uimenu(app.ContextMenu3_3);
            app.ChangeLegendMenu_3.MenuSelectedFcn = createCallbackFcn(app, @ChangeLegendMenu_3Selected, true);
            app.ChangeLegendMenu_3.Text = 'Change Legend';
            app.ChangeFontsizeMenu_3 = uimenu(app.ContextMenu3_3);
            app.ChangeFontsizeMenu_3.MenuSelectedFcn = createCallbackFcn(app, @ChangeFontsizeMenu_3Selected, true);
            app.ChangeFontsizeMenu_3.Text = 'Change Fontsize';
            % Assign app.ContextMenu3_3
            app.UIAxes_5.ContextMenu = app.ContextMenu3_3;
            % Show the figure after all components are created
            app.ContinousKilosortSpikeAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Continous_Kilosort_Spike_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ContinousKilosortSpikeAnalysisUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ContinousKilosortSpikeAnalysisUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ContinousKilosortSpikeAnalysisUIFigure)
        end
    end
end