classdef Continous_Waveform_Analysis_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UnitAnalysisWindowUIFigure      matlab.ui.Figure
        SnapshotMenu                    matlab.ui.container.Menu
        SaveSnapshotofWindowMenu        matlab.ui.container.Menu
        PNGMenu                         matlab.ui.container.Menu
        JPGMenu                         matlab.ui.container.Menu
        ExportDataMenu                  matlab.ui.container.Menu
        WaveformsMenu                   matlab.ui.container.Menu
        Plot1Menu                       matlab.ui.container.Menu
        matMenu                         matlab.ui.container.Menu
        txtMenu                         matlab.ui.container.Menu
        csvMenu                         matlab.ui.container.Menu
        Plot2Menu                       matlab.ui.container.Menu
        matMenu_2                       matlab.ui.container.Menu
        txtMenu_2                       matlab.ui.container.Menu
        csvMenu_2                       matlab.ui.container.Menu
        Plot3Menu                       matlab.ui.container.Menu
        matMenu_3                       matlab.ui.container.Menu
        txtMenu_3                       matlab.ui.container.Menu
        csvMenu_3                       matlab.ui.container.Menu
        ISIMenu                         matlab.ui.container.Menu
        Plot1Menu_2                     matlab.ui.container.Menu
        matMenu_4                       matlab.ui.container.Menu
        txtMenu_4                       matlab.ui.container.Menu
        csvMenu_4                       matlab.ui.container.Menu
        Plot2Menu_2                     matlab.ui.container.Menu
        matMenu_5                       matlab.ui.container.Menu
        txtMenu_5                       matlab.ui.container.Menu
        csvMenu_5                       matlab.ui.container.Menu
        Plot3Menu_2                     matlab.ui.container.Menu
        matMenu_6                       matlab.ui.container.Menu
        txtMenu_6                       matlab.ui.container.Menu
        csvMenu_6                       matlab.ui.container.Menu
        AutocorrelogramMenu             matlab.ui.container.Menu
        Plot1Menu_3                     matlab.ui.container.Menu
        matMenu_7                       matlab.ui.container.Menu
        txtMenu_7                       matlab.ui.container.Menu
        csvMenu_7                       matlab.ui.container.Menu
        Plot2Menu_3                     matlab.ui.container.Menu
        matMenu_8                       matlab.ui.container.Menu
        txtMenu_8                       matlab.ui.container.Menu
        csvMenu_8                       matlab.ui.container.Menu
        Plot3Menu_3                     matlab.ui.container.Menu
        matMenu_9                       matlab.ui.container.Menu
        txtMenu_9                       matlab.ui.container.Menu
        csvMenu_9                       matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        RightPanel                      matlab.ui.container.Panel
        PlotColumnSettingsPanel         matlab.ui.container.Panel
        NumWaveformsEditField_2         matlab.ui.control.EditField
        NumWaveformsEditField_2Label    matlab.ui.control.Label
        UnitstoShow123EditField_3       matlab.ui.control.EditField
        UnitstoShow123EditField_3Label  matlab.ui.control.Label
        NumWaveformsEditField_3         matlab.ui.control.EditField
        NumWaveformsEditField_3Label    matlab.ui.control.Label
        UnitstoShow123EditField_2       matlab.ui.control.EditField
        UnitstoShow123EditField_2Label  matlab.ui.control.Label
        UnitstoShow123EditField         matlab.ui.control.EditField
        UnitstoShow123EditFieldLabel    matlab.ui.control.Label
        NumWaveformsEditField           matlab.ui.control.EditField
        NumWaveformsEditFieldLabel      matlab.ui.control.Label
        UnitAnalysisWindowLabel         matlab.ui.control.Label
        NeuromodLabel                   matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        CloseButton                     matlab.ui.control.Button
        GeneralSettingsPanel            matlab.ui.container.Panel
        TimeLagmsEditField              matlab.ui.control.EditField
        TimeLagmsEditFieldLabel         matlab.ui.control.Label
        DeleteUnitButton                matlab.ui.control.Button
        DeleteUnitDropDown              matlab.ui.control.DropDown
        DeleteUnitDropDownLabel         matlab.ui.control.Label
        MaxISItoshowsEditField          matlab.ui.control.EditField
        MaxISItoshowsLabel              matlab.ui.control.Label
        BinNumberISIEditField           matlab.ui.control.EditField
        BinNumberISILabel               matlab.ui.control.Label
        UIAxes_7                        matlab.ui.control.UIAxes
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_1                        matlab.ui.control.UIAxes
        UIAxes_9                        matlab.ui.control.UIAxes
        UIAxes_8                        matlab.ui.control.UIAxes
        UIAxes_6                        matlab.ui.control.UIAxes
        UIAxes_5                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Mainapp = []; % Description
        AnalysisWindow = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp, AnalysisWindow)
            movegui(app.UnitAnalysisWindowUIFigure, 'center');
            
            app.Mainapp = Mainapp;
            app.AnalysisWindow = AnalysisWindow;
            
            if strcmp(AnalysisWindow,"EventWindow")
                app.UnitAnalysisWindowLabel.Text = "Event Unit Analysis Window";
            else
                app.UnitAnalysisWindowLabel.Text = "Continous Unit Analysis Window";
            end

            for i = 1:length(unique(app.Mainapp.Data.Spikes.SpikeCluster))
                app.DeleteUnitDropDown.Items{i} = convertStringsToChars(strcat("Unit"," ",num2str(i)));
            end

            app.NumWaveformsEditField.Value = "20";
            app.NumWaveformsEditField_2.Value = "20";
            app.NumWaveformsEditField_3.Value = "20";

            app.UnitstoShow123EditField.Value = "1,2";
            app.UnitstoShow123EditField_2.Value = "3,4";
            app.UnitstoShow123EditField_3.Value = "4,5";
            
            [Units,Waves,Wavefigs,ISIfigs,AutoCfigs,SpikeTimes,SpikePositions,SpikeCluster,SpikeWaveforms,SpikeChannel,app.TimeLagmsEditField.Value] = Spike_Module_Prepare_WaveForm_Window_and_Analysis(app.Mainapp.Data,app.UnitstoShow123EditField.Value,app.UnitstoShow123EditField_2.Value,app.UnitstoShow123EditField_3.Value,app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.NumWaveformsEditField_3.Value,app.UIAxes_1,app.UIAxes_2,app.UIAxes_3,app.UIAxes_4,app.UIAxes_5,app.UIAxes_6,app.UIAxes_7,app.UIAxes_8,app.UIAxes_9,"StartUp",app.AnalysisWindow,app.TimeLagmsEditField.Value);

            %% Plot Waveforms
           
            app.Mainapp.CurrentPlotData = Spike_Waveforms_Plot_Waveforms(app.Mainapp.Data,Units,SpikeWaveforms,SpikeCluster,Waves,Wavefigs,app.Mainapp.CurrentPlotData);

            %% Plot ISI
            
            [~,app.Mainapp.CurrentPlotData] = Spike_Module_Calculate_Plot_ISI(app.Mainapp.Data,SpikeTimes,SpikePositions,SpikeCluster,SpikeChannel,Units,Waves,ISIfigs,str2double(app.BinNumberISIEditField.Value),str2double(app.MaxISItoshowsEditField.Value),app.Mainapp.CurrentPlotData);
      
            %% Plot Autocorrelogramme

            app.Mainapp.CurrentPlotData = Spikes_Module_AutoCorrelogram(app.Mainapp.Data,SpikeTimes,SpikePositions,SpikeChannel,SpikeCluster,AutoCfigs,Units,str2double(app.BinNumberISIEditField.Value),app.Mainapp.CurrentPlotData,str2double(app.TimeLagmsEditField.Value));
      
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.Mainapp.UnitAnalysis = [];

            if ~isempty(app.Mainapp.ProbeViewWindowHandle) % delete option from probe view 
                for i = 1:length(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                    if strcmp(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Unit Analysis Window')
                        app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items(i) = [];
                        break;
                    end
                end
            else % if no probe view available
 
            end

            delete(app)
        end

        % Value changed function: UnitstoShow123EditField
        function UnitstoShow123EditFieldValueChanged(app, event)
            %% Check Inputs
            
            [app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.NumWaveformsEditField_3.Value,app.BinNumberISIEditField.Value,app.MaxISItoshowsEditField.Value] = Spike_Module_Prepare_WaveForm_Window_and_Analysis_Check_Inputs(app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.NumWaveformsEditField_3.Value,app.BinNumberISIEditField.Value,app.MaxISItoshowsEditField.Value);

            %% Prepare analyiss and plots
            [Units,Waves,Wavefigs,ISIfigs,AutoCfigs,SpikeTimes,SpikePositions,SpikeCluster,SpikeWaveforms,SpikeChannel,app.TimeLagmsEditField.Value] = Spike_Module_Prepare_WaveForm_Window_and_Analysis(app.Mainapp.Data,app.UnitstoShow123EditField.Value,app.UnitstoShow123EditField_2.Value,app.UnitstoShow123EditField_3.Value,app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.NumWaveformsEditField_3.Value,app.UIAxes_1,app.UIAxes_2,app.UIAxes_3,app.UIAxes_4,app.UIAxes_5,app.UIAxes_6,app.UIAxes_7,app.UIAxes_8,app.UIAxes_9,"U1",app.AnalysisWindow,app.TimeLagmsEditField.Value);
            
            %% Plot Waveforms
           
            app.Mainapp.CurrentPlotData = Spike_Waveforms_Plot_Waveforms(app.Mainapp.Data,Units,SpikeWaveforms,SpikeCluster,Waves,Wavefigs,app.Mainapp.CurrentPlotData);

            %% Plot ISI
            
            [~,app.Mainapp.CurrentPlotData] = Spike_Module_Calculate_Plot_ISI(app.Mainapp.Data,SpikeTimes,SpikePositions,SpikeCluster,SpikeChannel,Units,Waves,ISIfigs,str2double(app.BinNumberISIEditField.Value),str2double(app.MaxISItoshowsEditField.Value),app.Mainapp.CurrentPlotData);
      
            %% Plot Autocorrelogramme

            app.Mainapp.CurrentPlotData = Spikes_Module_AutoCorrelogram(app.Mainapp.Data,SpikeTimes,SpikePositions,SpikeChannel,SpikeCluster,AutoCfigs,Units,str2double(app.BinNumberISIEditField.Value),app.Mainapp.CurrentPlotData,str2double(app.TimeLagmsEditField.Value));
      
        end

        % Value changed function: NumWaveformsEditField
        function NumWaveformsEditFieldValueChanged(app, event)
            %% Check Inputs
            
            [app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.NumWaveformsEditField_3.Value,app.BinNumberISIEditField.Value,app.MaxISItoshowsEditField.Value] = Spike_Module_Prepare_WaveForm_Window_and_Analysis_Check_Inputs(app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.NumWaveformsEditField_3.Value,app.BinNumberISIEditField.Value,app.MaxISItoshowsEditField.Value);


            [Units,Waves,Wavefigs,ISIfigs,AutoCfigs,SpikeTimes,SpikePositions,SpikeCluster,SpikeWaveforms,SpikeChannel,app.TimeLagmsEditField.Value] = Spike_Module_Prepare_WaveForm_Window_and_Analysis(app.Mainapp.Data,app.UnitstoShow123EditField.Value,app.UnitstoShow123EditField_2.Value,app.UnitstoShow123EditField_3.Value,app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.NumWaveformsEditField_3.Value,app.UIAxes_1,app.UIAxes_2,app.UIAxes_3,app.UIAxes_4,app.UIAxes_5,app.UIAxes_6,app.UIAxes_7,app.UIAxes_8,app.UIAxes_9,"W1",app.AnalysisWindow,app.TimeLagmsEditField.Value);
            
            %% Plot Waveforms
           
            app.Mainapp.CurrentPlotData = Spike_Waveforms_Plot_Waveforms(app.Mainapp.Data,Units,SpikeWaveforms,SpikeCluster,Waves,Wavefigs,app.Mainapp.CurrentPlotData);

        end

        % Value changed function: NumWaveformsEditField_2
        function NumWaveformsEditField_2ValueChanged(app, event)
            %% Check Inputs
            
            [app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.NumWaveformsEditField_3.Value,app.BinNumberISIEditField.Value,app.MaxISItoshowsEditField.Value] = Spike_Module_Prepare_WaveForm_Window_and_Analysis_Check_Inputs(app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.NumWaveformsEditField_3.Value,app.BinNumberISIEditField.Value,app.MaxISItoshowsEditField.Value);


            [Units,Waves,Wavefigs,ISIfigs,AutoCfigs,SpikeTimes,SpikePositions,SpikeCluster,SpikeWaveforms,SpikeChannel,app.TimeLagmsEditField.Value] = Spike_Module_Prepare_WaveForm_Window_and_Analysis(app.Mainapp.Data,app.UnitstoShow123EditField.Value,app.UnitstoShow123EditField_2.Value,app.UnitstoShow123EditField_3.Value,app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.NumWaveformsEditField_3.Value,app.UIAxes_1,app.UIAxes_2,app.UIAxes_3,app.UIAxes_4,app.UIAxes_5,app.UIAxes_6,app.UIAxes_7,app.UIAxes_8,app.UIAxes_9,"W2",app.AnalysisWindow,app.TimeLagmsEditField.Value);
            
            %% Plot Waveforms
           
            app.Mainapp.CurrentPlotData = Spike_Waveforms_Plot_Waveforms(app.Mainapp.Data,Units,SpikeWaveforms,SpikeCluster,Waves,Wavefigs,app.Mainapp.CurrentPlotData);

        end

        % Value changed function: UnitstoShow123EditField_2
        function UnitstoShow123EditField_2ValueChanged(app, event)
            %% Check Inputs
            
            [app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.NumWaveformsEditField_3.Value,app.BinNumberISIEditField.Value,app.MaxISItoshowsEditField.Value] = Spike_Module_Prepare_WaveForm_Window_and_Analysis_Check_Inputs(app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.NumWaveformsEditField_3.Value,app.BinNumberISIEditField.Value,app.MaxISItoshowsEditField.Value);

            [Units,Waves,Wavefigs,ISIfigs,AutoCfigs,SpikeTimes,SpikePositions,SpikeCluster,SpikeWaveforms,SpikeChannel,app.TimeLagmsEditField.Value] = Spike_Module_Prepare_WaveForm_Window_and_Analysis(app.Mainapp.Data,app.UnitstoShow123EditField.Value,app.UnitstoShow123EditField_2.Value,app.UnitstoShow123EditField_3.Value,app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.NumWaveformsEditField_3.Value,app.UIAxes_1,app.UIAxes_2,app.UIAxes_3,app.UIAxes_4,app.UIAxes_5,app.UIAxes_6,app.UIAxes_7,app.UIAxes_8,app.UIAxes_9,"U2",app.AnalysisWindow,app.TimeLagmsEditField.Value);
            
            %% Plot Waveforms
           
            app.Mainapp.CurrentPlotData = Spike_Waveforms_Plot_Waveforms(app.Mainapp.Data,Units,SpikeWaveforms,SpikeCluster,Waves,Wavefigs,app.Mainapp.CurrentPlotData);

            %% Plot ISI
            
            [~,app.Mainapp.CurrentPlotData] = Spike_Module_Calculate_Plot_ISI(app.Mainapp.Data,SpikeTimes,SpikePositions,SpikeCluster,SpikeChannel,Units,Waves,ISIfigs,str2double(app.BinNumberISIEditField.Value),str2double(app.MaxISItoshowsEditField.Value),app.Mainapp.CurrentPlotData);
      
            %% Plot Autocorrelogramme

            app.Mainapp.CurrentPlotData = Spikes_Module_AutoCorrelogram(app.Mainapp.Data,SpikeTimes,SpikePositions,SpikeChannel,SpikeCluster,AutoCfigs,Units,str2double(app.BinNumberISIEditField.Value),app.Mainapp.CurrentPlotData,str2double(app.TimeLagmsEditField.Value));
            
        end

        % Value changed function: UnitstoShow123EditField_3
        function UnitstoShow123EditField_3ValueChanged(app, event)
            %% Check Inputs
            
            [app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.NumWaveformsEditField_3.Value,app.BinNumberISIEditField.Value,app.MaxISItoshowsEditField.Value] = Spike_Module_Prepare_WaveForm_Window_and_Analysis_Check_Inputs(app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.NumWaveformsEditField_3.Value,app.BinNumberISIEditField.Value,app.MaxISItoshowsEditField.Value);

            [Units,Waves,Wavefigs,ISIfigs,AutoCfigs,SpikeTimes,SpikePositions,SpikeCluster,SpikeWaveforms,SpikeChannel,app.TimeLagmsEditField.Value] = Spike_Module_Prepare_WaveForm_Window_and_Analysis(app.Mainapp.Data,app.UnitstoShow123EditField.Value,app.UnitstoShow123EditField_2.Value,app.UnitstoShow123EditField_3.Value,app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.NumWaveformsEditField_3.Value,app.UIAxes_1,app.UIAxes_2,app.UIAxes_3,app.UIAxes_4,app.UIAxes_5,app.UIAxes_6,app.UIAxes_7,app.UIAxes_8,app.UIAxes_9,"U3",app.AnalysisWindow,app.TimeLagmsEditField.Value);
            
            %% Plot Waveforms
           
            app.Mainapp.CurrentPlotData = Spike_Waveforms_Plot_Waveforms(app.Mainapp.Data,Units,SpikeWaveforms,SpikeCluster,Waves,Wavefigs,app.Mainapp.CurrentPlotData);

            %% Plot ISI
            
            [~,app.Mainapp.CurrentPlotData] = Spike_Module_Calculate_Plot_ISI(app.Mainapp.Data,SpikeTimes,SpikePositions,SpikeCluster,SpikeChannel,Units,Waves,ISIfigs,str2double(app.BinNumberISIEditField.Value),str2double(app.MaxISItoshowsEditField.Value),app.Mainapp.CurrentPlotData);
      
            %% Plot Autocorrelogramme

            app.Mainapp.CurrentPlotData = Spikes_Module_AutoCorrelogram(app.Mainapp.Data,SpikeTimes,SpikePositions,SpikeChannel,SpikeCluster,AutoCfigs,Units,str2double(app.BinNumberISIEditField.Value),app.Mainapp.CurrentPlotData,str2double(app.TimeLagmsEditField.Value));
            
        end

        % Value changed function: NumWaveformsEditField_3
        function NumWaveformsEditField_3ValueChanged(app, event)
            %% Check Inputs
            
            [app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.NumWaveformsEditField_3.Value,app.BinNumberISIEditField.Value,app.MaxISItoshowsEditField.Value] = Spike_Module_Prepare_WaveForm_Window_and_Analysis_Check_Inputs(app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.NumWaveformsEditField_3.Value,app.BinNumberISIEditField.Value,app.MaxISItoshowsEditField.Value);


            [Units,Waves,Wavefigs,ISIfigs,AutoCfigs,SpikeTimes,SpikePositions,SpikeCluster,SpikeWaveforms,SpikeChannel,app.TimeLagmsEditField.Value] = Spike_Module_Prepare_WaveForm_Window_and_Analysis(app.Mainapp.Data,app.UnitstoShow123EditField.Value,app.UnitstoShow123EditField_2.Value,app.UnitstoShow123EditField_3.Value,app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.NumWaveformsEditField_3.Value,app.UIAxes_1,app.UIAxes_2,app.UIAxes_3,app.UIAxes_4,app.UIAxes_5,app.UIAxes_6,app.UIAxes_7,app.UIAxes_8,app.UIAxes_9,"W3",app.AnalysisWindow,app.TimeLagmsEditField.Value);
            
            %% Plot Waveforms
           
            app.Mainapp.CurrentPlotData = Spike_Waveforms_Plot_Waveforms(app.Mainapp.Data,Units,SpikeWaveforms,SpikeCluster,Waves,Wavefigs,app.Mainapp.CurrentPlotData);

        end

        % Menu selected function: PNGMenu
        function PNGMenuSelected(app, event)
            [file, path] = uiputfile('*.png', 'Save as');

            exportapp(app.UnitAnalysisWindowUIFigure,fullfile(path,file)) 
        end

        % Menu selected function: JPGMenu
        function JPGMenuSelected(app, event)
            [file, path] = uiputfile('*.jpg', 'Save as');

            exportapp(app.UnitAnalysisWindowUIFigure,fullfile(path,file)) 
        end

        % Value changed function: MaxISItoshowsEditField
        function MaxISItoshowsEditFieldValueChanged(app, event)
            %% Check Inputs
            
            [app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.NumWaveformsEditField_3.Value,app.BinNumberISIEditField.Value,app.MaxISItoshowsEditField.Value] = Spike_Module_Prepare_WaveForm_Window_and_Analysis_Check_Inputs(app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.NumWaveformsEditField_3.Value,app.BinNumberISIEditField.Value,app.MaxISItoshowsEditField.Value);


            [Units,Waves,Wavefigs,ISIfigs,AutoCfigs,SpikeTimes,SpikePositions,SpikeCluster,SpikeWaveforms,SpikeChannel,app.TimeLagmsEditField.Value] = Spike_Module_Prepare_WaveForm_Window_and_Analysis(app.Mainapp.Data,app.UnitstoShow123EditField.Value,app.UnitstoShow123EditField_2.Value,app.UnitstoShow123EditField_3.Value,app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.NumWaveformsEditField_3.Value,app.UIAxes_1,app.UIAxes_2,app.UIAxes_3,app.UIAxes_4,app.UIAxes_5,app.UIAxes_6,app.UIAxes_7,app.UIAxes_8,app.UIAxes_9,"BinSize",app.AnalysisWindow,app.TimeLagmsEditField.Value);

            %% Plot ISI
            
            [~,app.Mainapp.CurrentPlotData] = Spike_Module_Calculate_Plot_ISI(app.Mainapp.Data,SpikeTimes,SpikePositions,SpikeCluster,SpikeChannel,Units,Waves,ISIfigs,str2double(app.BinNumberISIEditField.Value),str2double(app.MaxISItoshowsEditField.Value),app.Mainapp.CurrentPlotData);
         
        end

        % Value changed function: BinNumberISIEditField
        function BinNumberISIEditFieldValueChanged(app, event)
            %% Check Inputs
            
            [app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.NumWaveformsEditField_3.Value,app.BinNumberISIEditField.Value,app.MaxISItoshowsEditField.Value] = Spike_Module_Prepare_WaveForm_Window_and_Analysis_Check_Inputs(app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.NumWaveformsEditField_3.Value,app.BinNumberISIEditField.Value,app.MaxISItoshowsEditField.Value);

            [Units,Waves,Wavefigs,ISIfigs,AutoCfigs,SpikeTimes,SpikePositions,SpikeCluster,SpikeWaveforms,SpikeChannel,app.TimeLagmsEditField.Value] = Spike_Module_Prepare_WaveForm_Window_and_Analysis(app.Mainapp.Data,app.UnitstoShow123EditField.Value,app.UnitstoShow123EditField_2.Value,app.UnitstoShow123EditField_3.Value,app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.NumWaveformsEditField_3.Value,app.UIAxes_1,app.UIAxes_2,app.UIAxes_3,app.UIAxes_4,app.UIAxes_5,app.UIAxes_6,app.UIAxes_7,app.UIAxes_8,app.UIAxes_9,"ISIMaxTime",app.AnalysisWindow,app.TimeLagmsEditField.Value);
            
            %% Plot ISI
            
            [~,app.Mainapp.CurrentPlotData] = Spike_Module_Calculate_Plot_ISI(app.Mainapp.Data,SpikeTimes,SpikePositions,SpikeCluster,SpikeChannel,Units,Waves,ISIfigs,str2double(app.BinNumberISIEditField.Value),str2double(app.MaxISItoshowsEditField.Value),app.Mainapp.CurrentPlotData);
      
            %% Plot Autocorrelogramme

            app.Mainapp.CurrentPlotData = Spikes_Module_AutoCorrelogram(app.Mainapp.Data,SpikeTimes,SpikePositions,SpikeChannel,SpikeCluster,AutoCfigs,Units,str2double(app.BinNumberISIEditField.Value),app.Mainapp.CurrentPlotData,str2double(app.TimeLagmsEditField.Value));
            
        end

        % Value changing function: NumWaveformsEditField_2
        function NumWaveformsEditField_2ValueChanging(app, event)
           
            
        end

        % Button pushed function: DeleteUnitButton
        function DeleteUnitButtonPushed(app, event)
            [app.Mainapp.Data] = Utility_Delete_Units(app.Mainapp.Data,app.DeleteUnitDropDown.Value);
            
            Placeholder{1} = '';
            app.DeleteUnitDropDown.Items = Placeholder;
            for i = 1:length(unique(app.Mainapp.Data.Spikes.SpikeCluster))
                app.DeleteUnitDropDown.Items{i} = convertStringsToChars(strcat("Unit"," ",num2str(i)));
            end

            msgbox(strcat("Succesfully deleted ",app.DeleteUnitDropDown.Value,". Unit numbers adjusted accordingly. Plots are updated."))

            [Units,Waves,Wavefigs,ISIfigs,AutoCfigs,SpikeTimes,SpikePositions,SpikeCluster,SpikeWaveforms,SpikeChannel,app.TimeLagmsEditField.Value] = Spike_Module_Prepare_WaveForm_Window_and_Analysis(app.Mainapp.Data,app.UnitstoShow123EditField.Value,app.UnitstoShow123EditField_2.Value,app.UnitstoShow123EditField_3.Value,app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.NumWaveformsEditField_3.Value,app.UIAxes_1,app.UIAxes_2,app.UIAxes_3,app.UIAxes_4,app.UIAxes_5,app.UIAxes_6,app.UIAxes_7,app.UIAxes_8,app.UIAxes_9,"StartUp",app.AnalysisWindow,app.TimeLagmsEditField.Value);

            %% Plot Waveforms
           
            app.Mainapp.CurrentPlotData = Spike_Waveforms_Plot_Waveforms(app.Mainapp.Data,Units,SpikeWaveforms,SpikeCluster,Waves,Wavefigs,app.Mainapp.CurrentPlotData);

            %% Plot ISI
            
            [~,app.Mainapp.CurrentPlotData] = Spike_Module_Calculate_Plot_ISI(app.Mainapp.Data,SpikeTimes,SpikePositions,SpikeCluster,SpikeChannel,Units,Waves,ISIfigs,str2double(app.BinNumberISIEditField.Value),str2double(app.MaxISItoshowsEditField.Value),app.Mainapp.CurrentPlotData);
      
            %% Plot Autocorrelogramme

            app.Mainapp.CurrentPlotData = Spikes_Module_AutoCorrelogram(app.Mainapp.Data,SpikeTimes,SpikePositions,SpikeChannel,SpikeCluster,AutoCfigs,Units,str2double(app.BinNumberISIEditField.Value),app.Mainapp.CurrentPlotData,str2double(app.TimeLagmsEditField.Value));
      

        end

        % Value changed function: DeleteUnitDropDown
        function DeleteUnitDropDownValueChanged(app, event)
                     
        end

        % Menu selected function: matMenu
        function matMenuSelected(app, event)
            TimeRangePlot = size(app.Mainapp.Data.Spikes.Waveforms,2)/app.Mainapp.Data.Info.NativeSamplingRate;
            
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continous Unit(s) ",app.UnitstoShow123EditField.Value," Waveform Analysis Plot ",num2str(1)));
        end

        % Menu selected function: txtMenu
        function txtMenuSelected(app, event)
            TimeRangePlot = size(app.Mainapp.Data.Spikes.Waveforms,2)/app.Mainapp.Data.Info.NativeSamplingRate;
            
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continous Unit(s) ",app.UnitstoShow123EditField.Value," Waveform Analysis Plot ",num2str(1)));
        end

        % Menu selected function: csvMenu
        function csvMenuSelected(app, event)
            TimeRangePlot = size(app.Mainapp.Data.Spikes.Waveforms,2)/app.Mainapp.Data.Info.NativeSamplingRate;
            
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".csv",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continous Unit(s) ",app.UnitstoShow123EditField.Value," Waveform Analysis Plot ",num2str(1)));
        end

        % Menu selected function: matMenu_2
        function matMenu_2Selected(app, event)
            TimeRangePlot = size(app.Mainapp.Data.Spikes.Waveforms,2)/app.Mainapp.Data.Info.NativeSamplingRate;
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continous Unit(s) ",app.UnitstoShow123EditField_2.Value," Waveform Analysis Plot ",num2str(2)));
        end

        % Menu selected function: txtMenu_2
        function txtMenu_2Selected(app, event)
            TimeRangePlot = size(app.Mainapp.Data.Spikes.Waveforms,2)/app.Mainapp.Data.Info.NativeSamplingRate;
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continous Unit(s) ",app.UnitstoShow123EditField_2.Value," Waveform Analysis Plot ",num2str(2)));
        end

        % Menu selected function: csvMenu_2
        function csvMenu_2Selected(app, event)
            TimeRangePlot = size(app.Mainapp.Data.Spikes.Waveforms,2)/app.Mainapp.Data.Info.NativeSamplingRate;
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".csv",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continous Unit(s) ",app.UnitstoShow123EditField_2.Value," Waveform Analysis Plot ",num2str(2)));
        end

        % Menu selected function: matMenu_3
        function matMenu_3Selected(app, event)
            TimeRangePlot = size(app.Mainapp.Data.Spikes.Waveforms,2)/app.Mainapp.Data.Info.NativeSamplingRate;
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continous Unit(s) ",app.UnitstoShow123EditField_3.Value," Waveform Analysis Plot ",num2str(3)));
        end

        % Menu selected function: txtMenu_3
        function txtMenu_3Selected(app, event)
            TimeRangePlot = size(app.Mainapp.Data.Spikes.Waveforms,2)/app.Mainapp.Data.Info.NativeSamplingRate;
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continous Unit(s) ",app.UnitstoShow123EditField_3.Value," Waveform Analysis Plot ",num2str(3)));
        end

        % Menu selected function: csvMenu_3
        function csvMenu_3Selected(app, event)
            TimeRangePlot = size(app.Mainapp.Data.Spikes.Waveforms,2)/app.Mainapp.Data.Info.NativeSamplingRate;
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".csv",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continous Unit(s) ",app.UnitstoShow123EditField_3.Value," Waveform Analysis Plot ",num2str(3)));
        end

        % Menu selected function: matMenu_4
        function matMenu_4Selected(app, event)
            TimeRangePlot = size(app.Mainapp.Data.Spikes.Waveforms,2)/app.Mainapp.Data.Info.NativeSamplingRate;
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continous Unit(s) ",app.UnitstoShow123EditField.Value," ISI Analyis Plot ",num2str(1)));
        end

        % Menu selected function: txtMenu_4
        function txtMenu_4Selected(app, event)
            TimeRangePlot = size(app.Mainapp.Data.Spikes.Waveforms,2)/app.Mainapp.Data.Info.NativeSamplingRate;
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continous Unit(s) ",app.UnitstoShow123EditField.Value," ISI Analyis Plot ",num2str(1)));
        end

        % Menu selected function: csvMenu_4
        function csvMenu_4Selected(app, event)
            TimeRangePlot = size(app.Mainapp.Data.Spikes.Waveforms,2)/app.Mainapp.Data.Info.NativeSamplingRate;
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".csv",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continous Unit(s) ",app.UnitstoShow123EditField.Value," ISI Analyis Plot ",num2str(1)));
        end

        % Menu selected function: matMenu_5
        function matMenu_5Selected(app, event)
            TimeRangePlot = size(app.Mainapp.Data.Spikes.Waveforms,2)/app.Mainapp.Data.Info.NativeSamplingRate;
         
            StartTime = 0;
            
            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continous Unit(s) ",app.UnitstoShow123EditField_2.Value," ISI Analyis Plot ",num2str(2)));
        end

        % Menu selected function: txtMenu_5
        function txtMenu_5Selected(app, event)
            TimeRangePlot = size(app.Mainapp.Data.Spikes.Waveforms,2)/app.Mainapp.Data.Info.NativeSamplingRate;
         
            StartTime = 0;
            
            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continous Unit(s) ",app.UnitstoShow123EditField_2.Value," ISI Analyis Plot ",num2str(2)));
        end

        % Menu selected function: csvMenu_5
        function csvMenu_5Selected(app, event)
            TimeRangePlot = size(app.Mainapp.Data.Spikes.Waveforms,2)/app.Mainapp.Data.Info.NativeSamplingRate;
         
            StartTime = 0;
            
            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".csv",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continous Unit(s) ",app.UnitstoShow123EditField_2.Value," ISI Analyis Plot ",num2str(2)));
        end

        % Menu selected function: matMenu_6
        function matMenu_6Selected(app, event)
            TimeRangePlot = size(app.Mainapp.Data.Spikes.Waveforms,2)/app.Mainapp.Data.Info.NativeSamplingRate;
         
            StartTime = 0;
            
            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continous Unit(s) ",app.UnitstoShow123EditField_3.Value," ISI Analyis Plot ",num2str(3)));
        end

        % Menu selected function: txtMenu_6
        function txtMenu_6Selected(app, event)
            TimeRangePlot = size(app.Mainapp.Data.Spikes.Waveforms,2)/app.Mainapp.Data.Info.NativeSamplingRate;
         
            StartTime = 0;
            
            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continous Unit(s) ",app.UnitstoShow123EditField_3.Value," ISI Analyis Plot ",num2str(3)));
        end

        % Menu selected function: csvMenu_6
        function csvMenu_6Selected(app, event)
            TimeRangePlot = size(app.Mainapp.Data.Spikes.Waveforms,2)/app.Mainapp.Data.Info.NativeSamplingRate;
         
            StartTime = 0;
            
            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".csv",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continous Unit(s) ",app.UnitstoShow123EditField_3.Value," ISI Analyis Plot ",num2str(3)));
        end

        % Menu selected function: matMenu_7
        function matMenu_7Selected(app, event)
            TimeRangePlot = size(app.Mainapp.Data.Spikes.Waveforms,2)/app.Mainapp.Data.Info.NativeSamplingRate;
         
            StartTime = 0;
            
            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continous Unit(s) ",app.UnitstoShow123EditField.Value," Autocorrelogram Analyis Plot ",num2str(1)));
        end

        % Menu selected function: txtMenu_7
        function txtMenu_7Selected(app, event)
            TimeRangePlot = size(app.Mainapp.Data.Spikes.Waveforms,2)/app.Mainapp.Data.Info.NativeSamplingRate;
         
            StartTime = 0;
            
            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continous Unit(s) ",app.UnitstoShow123EditField.Value," Autocorrelogram Analyis Plot ",num2str(1)));
        end

        % Menu selected function: csvMenu_7
        function csvMenu_7Selected(app, event)
            TimeRangePlot = size(app.Mainapp.Data.Spikes.Waveforms,2)/app.Mainapp.Data.Info.NativeSamplingRate;
         
            StartTime = 0;
            
            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".csv",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continous Unit(s) ",app.UnitstoShow123EditField.Value," Autocorrelogram Analyis Plot ",num2str(1)));
        end

        % Menu selected function: matMenu_8
        function matMenu_8Selected(app, event)
            TimeRangePlot = size(app.Mainapp.Data.Spikes.Waveforms,2)/app.Mainapp.Data.Info.NativeSamplingRate;
         
            StartTime = 0;
            
            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continous Unit(s) ",app.UnitstoShow123EditField_2.Value," Autocorrelogram Analyis Plot ",num2str(2)));
        end

        % Menu selected function: txtMenu_8
        function txtMenu_8Selected(app, event)
            TimeRangePlot = size(app.Mainapp.Data.Spikes.Waveforms,2)/app.Mainapp.Data.Info.NativeSamplingRate;
         
            StartTime = 0;
            
            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continous Unit(s) ",app.UnitstoShow123EditField_2.Value," Autocorrelogram Analyis Plot ",num2str(2)));
        end

        % Menu selected function: csvMenu_8
        function csvMenu_8Selected(app, event)
            TimeRangePlot = size(app.Mainapp.Data.Spikes.Waveforms,2)/app.Mainapp.Data.Info.NativeSamplingRate;
         
            StartTime = 0;
            
            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".csv",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continous Unit(s) ",app.UnitstoShow123EditField_2.Value," Autocorrelogram Analyis Plot ",num2str(2)));
        end

        % Menu selected function: matMenu_9
        function matMenu_9Selected(app, event)
            TimeRangePlot = size(app.Mainapp.Data.Spikes.Waveforms,2)/app.Mainapp.Data.Info.NativeSamplingRate;
         
            StartTime = 0;
            
            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continous Unit(s) ",app.UnitstoShow123EditField_3.Value," Autocorrelogram Analyis Plot ",num2str(3)));
        end

        % Menu selected function: txtMenu_9
        function txtMenu_9Selected(app, event)
            TimeRangePlot = size(app.Mainapp.Data.Spikes.Waveforms,2)/app.Mainapp.Data.Info.NativeSamplingRate;
         
            StartTime = 0;
            
            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continous Unit(s) ",app.UnitstoShow123EditField_3.Value," Autocorrelogram Analyis Plot ",num2str(3)));
        end

        % Menu selected function: csvMenu_9
        function csvMenu_9Selected(app, event)
            TimeRangePlot = size(app.Mainapp.Data.Spikes.Waveforms,2)/app.Mainapp.Data.Info.NativeSamplingRate;
         
            StartTime = 0;
            
            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".csv",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continous Unit(s) ",app.UnitstoShow123EditField_3.Value," Autocorrelogram Analyis Plot ",num2str(3)));
        end

        % Value changed function: TimeLagmsEditField
        function TimeLagmsEditFieldValueChanged(app, event)
            
            %% Check Inputs
            
            [app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.NumWaveformsEditField_3.Value,app.BinNumberISIEditField.Value,app.MaxISItoshowsEditField.Value] = Spike_Module_Prepare_WaveForm_Window_and_Analysis_Check_Inputs(app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.NumWaveformsEditField_3.Value,app.BinNumberISIEditField.Value,app.MaxISItoshowsEditField.Value);

            %% Prepare analyiss and plots
            [Units,Waves,Wavefigs,ISIfigs,AutoCfigs,SpikeTimes,SpikePositions,SpikeCluster,SpikeWaveforms,SpikeChannel,app.TimeLagmsEditField.Value] = Spike_Module_Prepare_WaveForm_Window_and_Analysis(app.Mainapp.Data,app.UnitstoShow123EditField.Value,app.UnitstoShow123EditField_2.Value,app.UnitstoShow123EditField_3.Value,app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.NumWaveformsEditField_3.Value,app.UIAxes_1,app.UIAxes_2,app.UIAxes_3,app.UIAxes_4,app.UIAxes_5,app.UIAxes_6,app.UIAxes_7,app.UIAxes_8,app.UIAxes_9,"AutoTimeLag",app.AnalysisWindow,app.TimeLagmsEditField.Value);
            
            %% Plot Autocorrelogramme

            app.Mainapp.CurrentPlotData = Spikes_Module_AutoCorrelogram(app.Mainapp.Data,SpikeTimes,SpikePositions,SpikeChannel,SpikeCluster,AutoCfigs,Units,str2double(app.BinNumberISIEditField.Value),app.Mainapp.CurrentPlotData,str2double(app.TimeLagmsEditField.Value));
      
        end

        % Close request function: UnitAnalysisWindowUIFigure
        function UnitAnalysisWindowUIFigureCloseRequest(app, event)
            app.Mainapp.UnitAnalysis = [];

            if ~isempty(app.Mainapp.ProbeViewWindowHandle) % delete option from probe view 
                for i = 1:length(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                    if strcmp(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Unit Analysis Window')
                        app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items(i) = [];
                        break;
                    end
                end
            else % if no probe view available
 
            end

            delete(app)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UnitAnalysisWindowUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {819, 819};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UnitAnalysisWindowUIFigure = uifigure('Visible', 'off');
            app.UnitAnalysisWindowUIFigure.Name = 'Unit Analysis Window';
            app.UnitAnalysisWindowUIFigure.Icon = 'Logo.png';
            app.UnitAnalysisWindowUIFigure.CloseRequestFcn = createCallbackFcn(app, @UnitAnalysisWindowUIFigureCloseRequest, true);
            app.UnitAnalysisWindowUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.SnapshotMenu = uimenu(app.UnitAnalysisWindowUIFigure);
            app.SnapshotMenu.Text = '   Snapshot   ';
            app.SaveSnapshotofWindowMenu = uimenu(app.SnapshotMenu);
            app.SaveSnapshotofWindowMenu.Text = 'Save Snapshot of Window';
            app.PNGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.PNGMenu.MenuSelectedFcn = createCallbackFcn(app, @PNGMenuSelected, true);
            app.PNGMenu.Text = 'PNG';
            app.JPGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.JPGMenu.MenuSelectedFcn = createCallbackFcn(app, @JPGMenuSelected, true);
            app.JPGMenu.Text = 'JPG';
            app.ExportDataMenu = uimenu(app.UnitAnalysisWindowUIFigure);
            app.ExportDataMenu.Text = '   Export Data   ';
            app.WaveformsMenu = uimenu(app.ExportDataMenu);
            app.WaveformsMenu.Text = 'Waveforms';
            app.Plot1Menu = uimenu(app.WaveformsMenu);
            app.Plot1Menu.Text = 'Plot 1';
            app.matMenu = uimenu(app.Plot1Menu);
            app.matMenu.MenuSelectedFcn = createCallbackFcn(app, @matMenuSelected, true);
            app.matMenu.Text = '.mat';
            app.txtMenu = uimenu(app.Plot1Menu);
            app.txtMenu.MenuSelectedFcn = createCallbackFcn(app, @txtMenuSelected, true);
            app.txtMenu.Text = '.txt';
            app.csvMenu = uimenu(app.Plot1Menu);
            app.csvMenu.MenuSelectedFcn = createCallbackFcn(app, @csvMenuSelected, true);
            app.csvMenu.Text = '.csv';
            app.Plot2Menu = uimenu(app.WaveformsMenu);
            app.Plot2Menu.Text = 'Plot 2';
            app.matMenu_2 = uimenu(app.Plot2Menu);
            app.matMenu_2.MenuSelectedFcn = createCallbackFcn(app, @matMenu_2Selected, true);
            app.matMenu_2.Text = '.mat';
            app.txtMenu_2 = uimenu(app.Plot2Menu);
            app.txtMenu_2.MenuSelectedFcn = createCallbackFcn(app, @txtMenu_2Selected, true);
            app.txtMenu_2.Text = '.txt';
            app.csvMenu_2 = uimenu(app.Plot2Menu);
            app.csvMenu_2.MenuSelectedFcn = createCallbackFcn(app, @csvMenu_2Selected, true);
            app.csvMenu_2.Text = '.csv';
            app.Plot3Menu = uimenu(app.WaveformsMenu);
            app.Plot3Menu.Text = 'Plot 3';
            app.matMenu_3 = uimenu(app.Plot3Menu);
            app.matMenu_3.MenuSelectedFcn = createCallbackFcn(app, @matMenu_3Selected, true);
            app.matMenu_3.Text = '.mat';
            app.txtMenu_3 = uimenu(app.Plot3Menu);
            app.txtMenu_3.MenuSelectedFcn = createCallbackFcn(app, @txtMenu_3Selected, true);
            app.txtMenu_3.Text = '.txt';
            app.csvMenu_3 = uimenu(app.Plot3Menu);
            app.csvMenu_3.MenuSelectedFcn = createCallbackFcn(app, @csvMenu_3Selected, true);
            app.csvMenu_3.Text = '.csv';
            app.ISIMenu = uimenu(app.ExportDataMenu);
            app.ISIMenu.Text = 'ISI';
            app.Plot1Menu_2 = uimenu(app.ISIMenu);
            app.Plot1Menu_2.Text = 'Plot 1';
            app.matMenu_4 = uimenu(app.Plot1Menu_2);
            app.matMenu_4.MenuSelectedFcn = createCallbackFcn(app, @matMenu_4Selected, true);
            app.matMenu_4.Text = '.mat';
            app.txtMenu_4 = uimenu(app.Plot1Menu_2);
            app.txtMenu_4.MenuSelectedFcn = createCallbackFcn(app, @txtMenu_4Selected, true);
            app.txtMenu_4.Text = '.txt';
            app.csvMenu_4 = uimenu(app.Plot1Menu_2);
            app.csvMenu_4.MenuSelectedFcn = createCallbackFcn(app, @csvMenu_4Selected, true);
            app.csvMenu_4.Text = '.csv';
            app.Plot2Menu_2 = uimenu(app.ISIMenu);
            app.Plot2Menu_2.Text = 'Plot 2';
            app.matMenu_5 = uimenu(app.Plot2Menu_2);
            app.matMenu_5.MenuSelectedFcn = createCallbackFcn(app, @matMenu_5Selected, true);
            app.matMenu_5.Text = '.mat';
            app.txtMenu_5 = uimenu(app.Plot2Menu_2);
            app.txtMenu_5.MenuSelectedFcn = createCallbackFcn(app, @txtMenu_5Selected, true);
            app.txtMenu_5.Text = '.txt';
            app.csvMenu_5 = uimenu(app.Plot2Menu_2);
            app.csvMenu_5.MenuSelectedFcn = createCallbackFcn(app, @csvMenu_5Selected, true);
            app.csvMenu_5.Text = '.csv';
            app.Plot3Menu_2 = uimenu(app.ISIMenu);
            app.Plot3Menu_2.Text = 'Plot 3';
            app.matMenu_6 = uimenu(app.Plot3Menu_2);
            app.matMenu_6.MenuSelectedFcn = createCallbackFcn(app, @matMenu_6Selected, true);
            app.matMenu_6.Text = '.mat';
            app.txtMenu_6 = uimenu(app.Plot3Menu_2);
            app.txtMenu_6.MenuSelectedFcn = createCallbackFcn(app, @txtMenu_6Selected, true);
            app.txtMenu_6.Text = '.txt';
            app.csvMenu_6 = uimenu(app.Plot3Menu_2);
            app.csvMenu_6.MenuSelectedFcn = createCallbackFcn(app, @csvMenu_6Selected, true);
            app.csvMenu_6.Text = '.csv';
            app.AutocorrelogramMenu = uimenu(app.ExportDataMenu);
            app.AutocorrelogramMenu.Text = 'Autocorrelogram';
            app.Plot1Menu_3 = uimenu(app.AutocorrelogramMenu);
            app.Plot1Menu_3.Text = 'Plot 1';
            app.matMenu_7 = uimenu(app.Plot1Menu_3);
            app.matMenu_7.MenuSelectedFcn = createCallbackFcn(app, @matMenu_7Selected, true);
            app.matMenu_7.Text = '.mat';
            app.txtMenu_7 = uimenu(app.Plot1Menu_3);
            app.txtMenu_7.MenuSelectedFcn = createCallbackFcn(app, @txtMenu_7Selected, true);
            app.txtMenu_7.Text = '.txt';
            app.csvMenu_7 = uimenu(app.Plot1Menu_3);
            app.csvMenu_7.MenuSelectedFcn = createCallbackFcn(app, @csvMenu_7Selected, true);
            app.csvMenu_7.Text = '.csv';
            app.Plot2Menu_3 = uimenu(app.AutocorrelogramMenu);
            app.Plot2Menu_3.Text = 'Plot 2';
            app.matMenu_8 = uimenu(app.Plot2Menu_3);
            app.matMenu_8.MenuSelectedFcn = createCallbackFcn(app, @matMenu_8Selected, true);
            app.matMenu_8.Text = '.mat';
            app.txtMenu_8 = uimenu(app.Plot2Menu_3);
            app.txtMenu_8.MenuSelectedFcn = createCallbackFcn(app, @txtMenu_8Selected, true);
            app.txtMenu_8.Text = '.txt';
            app.csvMenu_8 = uimenu(app.Plot2Menu_3);
            app.csvMenu_8.MenuSelectedFcn = createCallbackFcn(app, @csvMenu_8Selected, true);
            app.csvMenu_8.Text = '.csv';
            app.Plot3Menu_3 = uimenu(app.AutocorrelogramMenu);
            app.Plot3Menu_3.Text = 'Plot 3';
            app.matMenu_9 = uimenu(app.Plot3Menu_3);
            app.matMenu_9.MenuSelectedFcn = createCallbackFcn(app, @matMenu_9Selected, true);
            app.matMenu_9.Text = '.mat';
            app.txtMenu_9 = uimenu(app.Plot3Menu_3);
            app.txtMenu_9.MenuSelectedFcn = createCallbackFcn(app, @txtMenu_9Selected, true);
            app.txtMenu_9.Text = '.txt';
            app.csvMenu_9 = uimenu(app.Plot3Menu_3);
            app.csvMenu_9.MenuSelectedFcn = createCallbackFcn(app, @csvMenu_9Selected, true);
            app.csvMenu_9.Text = '.csv';
            app.GridLayout = uigridlayout(app.UnitAnalysisWindowUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes_2 = uiaxes(app.RightPanel);
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.RightPanel);
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_5 = uiaxes(app.RightPanel);
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_6 = uiaxes(app.RightPanel);
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_8 = uiaxes(app.RightPanel);
            zlabel(app.UIAxes_8, 'Z')
            app.UIAxes_9 = uiaxes(app.RightPanel);
            zlabel(app.UIAxes_9, 'Z')
            app.UIAxes_1 = uiaxes(app.RightPanel);
            zlabel(app.UIAxes_1, 'Z')
            app.UIAxes_4 = uiaxes(app.RightPanel);
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_7 = uiaxes(app.RightPanel);
            zlabel(app.UIAxes_7, 'Z')
            app.GeneralSettingsPanel = uipanel(app.RightPanel);
            app.GeneralSettingsPanel.Title = 'General Settings';
            app.BinNumberISILabel = uilabel(app.GeneralSettingsPanel);
            app.BinNumberISILabel.Text = 'Bin Number';
            app.BinNumberISIEditField = uieditfield(app.GeneralSettingsPanel, 'text');
            app.BinNumberISIEditField.ValueChangedFcn = createCallbackFcn(app, @BinNumberISIEditFieldValueChanged, true);
            app.BinNumberISIEditField.Value = '500';
            app.MaxISItoshowsLabel = uilabel(app.GeneralSettingsPanel);
            app.MaxISItoshowsLabel.Text = {'Max ISI to '; 'show [s]'};
            app.MaxISItoshowsEditField = uieditfield(app.GeneralSettingsPanel, 'text');
            app.MaxISItoshowsEditField.ValueChangedFcn = createCallbackFcn(app, @MaxISItoshowsEditFieldValueChanged, true);
            app.MaxISItoshowsEditField.Value = '0.150';
            app.DeleteUnitDropDownLabel = uilabel(app.GeneralSettingsPanel);
            app.DeleteUnitDropDownLabel.Text = 'Delete Unit';
            app.DeleteUnitDropDown = uidropdown(app.GeneralSettingsPanel);
            app.DeleteUnitDropDown.Items = {''};
            app.DeleteUnitDropDown.ValueChangedFcn = createCallbackFcn(app, @DeleteUnitDropDownValueChanged, true);
            app.DeleteUnitDropDown.Value = '';
            app.DeleteUnitButton = uibutton(app.GeneralSettingsPanel, 'push');
            app.DeleteUnitButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteUnitButtonPushed, true);
            app.DeleteUnitButton.Text = 'Delete Unit';
            app.TimeLagmsEditFieldLabel = uilabel(app.GeneralSettingsPanel);
            app.TimeLagmsEditFieldLabel.Text = 'Time Lag [ms]';
            app.TimeLagmsEditField = uieditfield(app.GeneralSettingsPanel, 'text');
            app.TimeLagmsEditField.ValueChangedFcn = createCallbackFcn(app, @TimeLagmsEditFieldValueChanged, true);
            app.TimeLagmsEditField.Value = '20';
            app.CloseButton = uibutton(app.RightPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodLabel = uilabel(app.RightPanel);
            app.NeuromodLabel.Text = 'Neuromod';
            app.UnitAnalysisWindowLabel = uilabel(app.RightPanel);
            app.UnitAnalysisWindowLabel.Text = 'Unit Analysis Window';
            app.PlotColumnSettingsPanel = uipanel(app.RightPanel);
            app.PlotColumnSettingsPanel.Title = 'Plot Column Settings';
            app.NumWaveformsEditFieldLabel = uilabel(app.PlotColumnSettingsPanel);
            app.NumWaveformsEditFieldLabel.Text = 'Num Waveforms';
            app.NumWaveformsEditField = uieditfield(app.PlotColumnSettingsPanel, 'text');
            app.NumWaveformsEditField.ValueChangedFcn = createCallbackFcn(app, @NumWaveformsEditFieldValueChanged, true);
            app.UnitstoShow123EditFieldLabel = uilabel(app.PlotColumnSettingsPanel);
            app.UnitstoShow123EditFieldLabel.Text = {'Units to Show'; '(1,2,3)'};
            app.UnitstoShow123EditField = uieditfield(app.PlotColumnSettingsPanel, 'text');
            app.UnitstoShow123EditField.ValueChangedFcn = createCallbackFcn(app, @UnitstoShow123EditFieldValueChanged, true);
            app.UnitstoShow123EditField_2Label = uilabel(app.PlotColumnSettingsPanel);
            app.UnitstoShow123EditField_2Label.Text = {'Units to Show'; '(1,2,3)'};
            app.UnitstoShow123EditField_2 = uieditfield(app.PlotColumnSettingsPanel, 'text');
            app.UnitstoShow123EditField_2.ValueChangedFcn = createCallbackFcn(app, @UnitstoShow123EditField_2ValueChanged, true);
            app.NumWaveformsEditField_3Label = uilabel(app.PlotColumnSettingsPanel);
            app.NumWaveformsEditField_3Label.Text = 'Num Waveforms';
            app.NumWaveformsEditField_3 = uieditfield(app.PlotColumnSettingsPanel, 'text');
            app.NumWaveformsEditField_3.ValueChangedFcn = createCallbackFcn(app, @NumWaveformsEditField_3ValueChanged, true);
            app.UnitstoShow123EditField_3Label = uilabel(app.PlotColumnSettingsPanel);
            app.UnitstoShow123EditField_3Label.Text = {'Units to Show'; '(1,2,3)'};
            app.UnitstoShow123EditField_3 = uieditfield(app.PlotColumnSettingsPanel, 'text');
            app.UnitstoShow123EditField_3.ValueChangedFcn = createCallbackFcn(app, @UnitstoShow123EditField_3ValueChanged, true);
            app.NumWaveformsEditField_2Label = uilabel(app.PlotColumnSettingsPanel);
            app.NumWaveformsEditField_2Label.Text = 'Num Waveforms';
            app.NumWaveformsEditField_2 = uieditfield(app.PlotColumnSettingsPanel, 'text');
            app.NumWaveformsEditField_2.ValueChangedFcn = createCallbackFcn(app, @NumWaveformsEditField_2ValueChanged, true);
            app.NumWaveformsEditField_2.ValueChangingFcn = createCallbackFcn(app, @NumWaveformsEditField_2ValueChanging, true);
            % Show the figure after all components are created
            app.UnitAnalysisWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Continous_Waveform_Analysis_Window(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UnitAnalysisWindowUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UnitAnalysisWindowUIFigure)
        end
    end
end