classdef Events_Internal_Spike_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EventRelatedInternalSpikeAnalysisUIFigure  matlab.ui.Figure
        SnapshotMenu                    matlab.ui.container.Menu
        SaveSnapshotofWindowMenu        matlab.ui.container.Menu
        PNGMenu                         matlab.ui.container.Menu
        JPGMenu                         matlab.ui.container.Menu
        PlotMenu                        matlab.ui.container.Menu
        Show2DimensionalPlotsMenu       matlab.ui.container.Menu
        Show3DimensionalPlotsMenu       matlab.ui.container.Menu
        ChangePlotPropertiesMenu        matlab.ui.container.Menu
        SpikeDataPlotMenu               matlab.ui.container.Menu
        FontSizeMenu                    matlab.ui.container.Menu
        LineWidthSpikesMenu_2           matlab.ui.container.Menu
        LineWidthTriggerMenu            matlab.ui.container.Menu
        ColorSpikesMenu                 matlab.ui.container.Menu
        ColorTriggerMenu                matlab.ui.container.Menu
        BackgorundColorMenu             matlab.ui.container.Menu
        XLabelMenu                      matlab.ui.container.Menu
        YLabelMenu                      matlab.ui.container.Menu
        RESETMenu                       matlab.ui.container.Menu
        SpikeRatePlotMenu               matlab.ui.container.Menu
        SpikeRateoverTimeMenu           matlab.ui.container.Menu
        FontSizeMenu_3                  matlab.ui.container.Menu
        BarColorMenu                    matlab.ui.container.Menu
        BackgroundColorMenu_2           matlab.ui.container.Menu
        XLabelMenu_3                    matlab.ui.container.Menu
        YLabelMenu_3                    matlab.ui.container.Menu
        RESETMenu_3                     matlab.ui.container.Menu
        SpikeRateoverDepthMenu_2        matlab.ui.container.Menu
        FontSizeMenu_5                  matlab.ui.container.Menu
        BarColorMenu_2                  matlab.ui.container.Menu
        BackgroundColorMenu_4           matlab.ui.container.Menu
        XLabelMenu_5                    matlab.ui.container.Menu
        YLabelMenu_5                    matlab.ui.container.Menu
        RESETMenu_5                     matlab.ui.container.Menu
        SaveCostumeAppearanceMenu       matlab.ui.container.Menu
        DeleteCostumeAppearanceMenu     matlab.ui.container.Menu
        HeatmapBinSizeMenu              matlab.ui.container.Menu
        ChangeBinSizeTimeMenu           matlab.ui.container.Menu
        ChangeBinSizeDepthMenu          matlab.ui.container.Menu
        ExportDataMenu                  matlab.ui.container.Menu
        SpikeRateMenu_2                 matlab.ui.container.Menu
        AllSpikesMenu                   matlab.ui.container.Menu
        OverTimeMenu                    matlab.ui.container.Menu
        matMenu                         matlab.ui.container.Menu
        txtMenu                         matlab.ui.container.Menu
        csvMenu                         matlab.ui.container.Menu
        OverChannelMenu                 matlab.ui.container.Menu
        matMenu_2                       matlab.ui.container.Menu
        txtMenu_2                       matlab.ui.container.Menu
        csvMenu_2                       matlab.ui.container.Menu
        SelectedUnitMenu                matlab.ui.container.Menu
        matMenu_3                       matlab.ui.container.Menu
        txtMenu_3                       matlab.ui.container.Menu
        csvMenu_3                       matlab.ui.container.Menu
        MainPlotMenu                    matlab.ui.container.Menu
        AllSpikesMenu_2                 matlab.ui.container.Menu
        matMenu_4                       matlab.ui.container.Menu
        txtMenu_4                       matlab.ui.container.Menu
        csvMenu_4                       matlab.ui.container.Menu
        SelectedUnitMenu_2              matlab.ui.container.Menu
        matMenu_5                       matlab.ui.container.Menu
        txtMenu_5                       matlab.ui.container.Menu
        csvMenu_5                       matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        RightPanel                      matlab.ui.container.Panel
        CloseButton                     matlab.ui.control.Button
        NeuromodLabel                   matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        EventRelatedInternalSpikeAnalysisLabel  matlab.ui.control.Label
        InformationLabel                matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        SpikeAnalysisOptionsPanel       matlab.ui.container.Panel
        ClustertoshowDropDown           matlab.ui.control.DropDown
        ClustertoShowDropDownLabel      matlab.ui.control.Label
        TimeWindowSpiketriggredLFPEditField  matlab.ui.control.EditField
        TimeWindowSpiketriggredLFPEditFieldLabel  matlab.ui.control.Label
        ChannelSelectionforPlottingEditField  matlab.ui.control.EditField
        ChannelSelectionforPlottingEditFieldLabel  matlab.ui.control.Label
        BaselineWindowStartStopinsEditField  matlab.ui.control.EditField
        BaselineWindowStartStopinsEditFieldLabel  matlab.ui.control.Label
        BaselineNormalizeCheckBox       matlab.ui.control.CheckBox
        AnalysisTypeDropDown            matlab.ui.control.DropDown
        AnalysisTypeDropDownLabel       matlab.ui.control.Label
        EventRangeEditField             matlab.ui.control.EditField
        EventRangeEditFieldLabel        matlab.ui.control.Label
        SpikeRateNumBinsEditField       matlab.ui.control.EditField
        SpikeRateNumBinsEditFieldLabel  matlab.ui.control.Label
        UIAxes_5                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        ContextMenu3                    matlab.ui.container.ContextMenu
        ChangeTimeBinSizeMenu           matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Mainapp = []; % Description
        Settings = [];
        SpikeData =[];
        SpikeBinSettings = [];
        rgbMatrix = [];
        SaveSpikeTriggereAveragePrepro = 0;
        TwoORThreeD = "TwoD"; % standard 2d plots when possible
        TimearoundEvent = [];
        numCluster = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            app.Mainapp = Mainapp;

            movegui(app.EventRelatedInternalSpikeAnalysisUIFigure, 'center');

            % Reset Spike Rate plot in order to show new bin size
            yyaxis(app.UIAxes_3, 'left');
            app.UIAxes_3.NextPlot = "replace"; 
            plot(app.UIAxes_3,0,0);
            app.UIAxes_3.NextPlot = "add";
            set(app.UIAxes_3, 'YDir','reverse');
            app.UIAxes_3.FontSize = 10;
            ylabel(app.UIAxes_3,"Spike Rate [Hz]")

            yyaxis(app.UIAxes_3, 'right');
            app.UIAxes_3.NextPlot = "replace"; 
            plot(app.UIAxes_3,0,0);
            app.UIAxes_3.NextPlot = "add";
            set(app.UIAxes_3, 'YDir','reverse');
            ylabel(app.UIAxes_3,"Spike Rate [Hz]")
            app.UIAxes_3.FontSize = 10;

            app.UIAxes.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotBackgroundColor;
            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotFontSize;
            
            app.UIAxes_3.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRTimePlotBackgroundColor;
            app.UIAxes_3.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRTimePlotFontSize;

            app.UIAxes_5.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRChannelPlotBackgroundColor;
            app.UIAxes_5.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRChannelPlotFontSize;

            spaceindicie = find(app.Mainapp.Data.Info.EventRelatedDataTimeRange==' ');
            Timebefore = str2double(app.Mainapp.Data.Info.EventRelatedDataTimeRange(1:spaceindicie(1)-1));

            app.TimeWindowSpiketriggredLFPEditField.Value = strcat('-0.050,',num2str(Timebefore));
            
            [app] = Spike_Module_Set_Up_Spike_Analysis_Windows(app,[],"EventWindow");

            app.BaselineNormalizeCheckBox.Enable = "on";
            app.BaselineWindowStartStopinsEditField.Enable = "on";
            
            [~,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Internal_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.ClustertoshowDropDown.Value,app.numCluster,app.Mainapp.CurrentPlotData,app.SpikeBinSettings,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel);
            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.Mainapp.EventInternalSpikesWindow = [];

            if ~isempty(app.Mainapp.ProbeViewWindowHandle) % delete option from probe view 
                for i = 1:length(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                    if strcmp(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Event Internal Spikes')
                        app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items(i) = [];
                        break;
                    end
                end
            else % if no probe view available
 
            end

            delete(app)
        end

        % Value changed function: SpikeRateNumBinsEditField
        function SpikeRateNumBinsEditFieldValueChanged(app, event)
            % Reset Spike Rate plot in order to show new bin size
            yyaxis(app.UIAxes_3, 'left');
            app.UIAxes_3.NextPlot = "replace"; 
            plot(app.UIAxes_3,0,0);
            app.UIAxes_3.NextPlot = "add";
            set(app.UIAxes_3, 'YDir','reverse');
            app.UIAxes_3.FontSize = 10;
            ylabel(app.UIAxes_3,"Spike Rate [Hz]")

            yyaxis(app.UIAxes_3, 'right');
            app.UIAxes_3.NextPlot = "replace"; 
            plot(app.UIAxes_3,0,0);
            app.UIAxes_3.NextPlot = "add";
            set(app.UIAxes_3, 'YDir','reverse');
            ylabel(app.UIAxes_3,"Spike Rate [Hz]")
            app.UIAxes_3.FontSize = 10;

            app.UIAxes_3.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRTimePlotBackgroundColor;
            app.UIAxes_3.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRTimePlotFontSize;

            app.UIAxes_5.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRChannelPlotBackgroundColor;
            app.UIAxes_5.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRChannelPlotFontSize;

            [~,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Internal_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.ClustertoshowDropDown.Value,app.numCluster,app.Mainapp.CurrentPlotData,app.SpikeBinSettings,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel);
            
        end

        % Menu selected function: PNGMenu
        function PNGMenuSelected(app, event)
            [file, path] = uiputfile('*.png', 'Save as');

            exportapp(app.EventRelatedInternalSpikeAnalysisUIFigure,fullfile(path,file)) 
        end

        % Menu selected function: JPGMenu
        function JPGMenuSelected(app, event)
            [file, path] = uiputfile('*.jpg', 'Save as');

            exportapp(app.EventRelatedInternalSpikeAnalysisUIFigure,fullfile(path,file)) 
        end

        % Value changed function: EventRangeEditField
        function EventRangeEditFieldValueChanged(app, event)
            % Reset Spike Rate plot in order to show new bin size
            yyaxis(app.UIAxes_3, 'left');
            app.UIAxes_3.NextPlot = "replace"; 
            plot(app.UIAxes_3,0,0);
            app.UIAxes_3.NextPlot = "add";
            set(app.UIAxes_3, 'YDir','reverse');
            app.UIAxes_3.FontSize = 10;
            ylabel(app.UIAxes_3,"Spike Rate [Hz]")

            yyaxis(app.UIAxes_3, 'right');
            app.UIAxes_3.NextPlot = "replace"; 
            plot(app.UIAxes_3,0,0);
            app.UIAxes_3.NextPlot = "add";
            set(app.UIAxes_3, 'YDir','reverse');
            ylabel(app.UIAxes_3,"Spike Rate [Hz]")
            app.UIAxes_3.FontSize = 10;

            app.UIAxes_3.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRTimePlotBackgroundColor;
            app.UIAxes_3.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRTimePlotFontSize;

            app.UIAxes_5.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRChannelPlotBackgroundColor;
            app.UIAxes_5.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRChannelPlotFontSize;

            [TempData,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Internal_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.ClustertoshowDropDown.Value,app.numCluster,app.Mainapp.CurrentPlotData,app.SpikeBinSettings,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel);
            
            if ~isempty(TempData)
                app.Mainapp.Data = TempData;
                PreviousChannelDeletetion = 0;
                % If Channel were deleted in previous preprocessing flag this
                % to reset main window parameter after + only allow for prepro
                % view if prepro was generated
                if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                    PreviousChannelDeletetion = 1;
                end
                %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                Utility_Show_Info_Loaded_Data(app.Mainapp);
                %% Wrap up preprocessing
                [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                if strcmp(app.Mainapp.DropDown.Value,'Preprocessed Data')
                    if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                        TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                        % Calculate the absolute differences
                        differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                        
                        % Find the index of the minimum difference
                        [~, app.Mainapp.CurrentTimePoints] = min(differences);
                    end
                end
                %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            end

            %% Plot spike rate -- have to extract spikes again since they have now to be normalized to event time
            %% Bc of Spike triggered avg this has to be computed again (to get spike time stamps not normalized to event time)
            
            [app.Mainapp.Data,Error] = Event_Spikes_Extract_Event_Related_Spikes(app.Mainapp.Data,'Internal',0);
          
            if Error == 1
                return;
            end

            %% Prepare Plots
            [PlotInfo,SpikeTimes,SpikePositions,~,SpikeCluster,~,~,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value] = Event_Spikes_Prepare_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.SpikeRateNumBinsEditField.Value,"Internal",0,app.TimeWindowSpiketriggredLFPEditField.Value,app.SpikeBinSettings,app.Mainapp.ActiveChannel);
            SpikePositions = (SpikePositions-1)*app.Mainapp.Data.Info.ChannelSpacing;
            app.Mainapp.CurrentPlotData = Event_Spikes_Plot_Spike_Rate(app.Mainapp.Data,PlotInfo.Time,"BinsizeChangeInitial",app.rgbMatrix,SpikeTimes,SpikePositions,SpikeCluster,length(PlotInfo.EventNr(1):PlotInfo.EventNr(2)),"Non",app.SpikeRateNumBinsEditField.Value,app.UIAxes_3,app.UIAxes_5,app.Mainapp.Data.Spikes.ChannelPosition,app.Mainapp.Data.Info.NativeSamplingRate,PlotInfo.ChannelsToPlot,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
       

        end

        % Value changed function: AnalysisTypeDropDown
        function AnalysisTypeDropDownValueChanged(app, event)
            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";
            set(app.UIAxes, 'YDir','reverse');
            set(app.UIAxes,'xticklabel',{[]});

            app.UIAxes.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotBackgroundColor;
            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotFontSize;

            if strcmp(app.AnalysisTypeDropDown.Value,"Spike Triggered Average")
                [app.Mainapp.Data,~] = Event_Spikes_Extract_Event_Related_Spikes(app.Mainapp.Data,'Kilosort',1);
            else
                if strcmp(event.PreviousValue,"Spike Triggered Average")
                    [app.Mainapp.Data,~] = Event_Spikes_Extract_Event_Related_Spikes(app.Mainapp.Data,'Kilosort',0);
                end
            end

            [TempData,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Internal_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.ClustertoshowDropDown.Value,app.numCluster,app.Mainapp.CurrentPlotData,app.SpikeBinSettings,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel);
            
            if strcmp(app.AnalysisTypeDropDown.Value,"Spike Rate Heatmap")
                app.BaselineNormalizeCheckBox.Enable = "on";
                app.BaselineWindowStartStopinsEditField.Enable = "on";
            else
                app.BaselineNormalizeCheckBox.Enable = "on";
                app.BaselineWindowStartStopinsEditField.Enable = "on";
            end

            if ~isempty(TempData)
                app.Mainapp.Data = TempData;
                PreviousChannelDeletetion = 0;
                % If Channel were deleted in previous preprocessing flag this
                % to reset main window parameter after + only allow for prepro
                % view if prepro was generated
                if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                    PreviousChannelDeletetion = 1;
                end
                %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                Utility_Show_Info_Loaded_Data(app.Mainapp);
                %% Wrap up preprocessing
                [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                if strcmp(app.Mainapp.DropDown.Value,'Preprocessed Data')
                    if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                        TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                        % Calculate the absolute differences
                        differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                        
                        % Find the index of the minimum difference
                        [~, app.Mainapp.CurrentTimePoints] = min(differences);
                    end
                end
                %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            end
       
        end

        % Value changed function: BaselineNormalizeCheckBox
        function BaselineNormalizeCheckBoxValueChanged(app, event)
            [~,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Internal_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.ClustertoshowDropDown.Value,app.numCluster,app.Mainapp.CurrentPlotData,app.SpikeBinSettings,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel);
            
        end

        % Value changed function: BaselineWindowStartStopinsEditField
        function BaselineWindowStartStopinsEditFieldValueChanged(app, event)
            [~,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Internal_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.ClustertoshowDropDown.Value,app.numCluster,app.Mainapp.CurrentPlotData,app.SpikeBinSettings,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel);
            
        end

        % Menu selected function: ChangeTimeBinSizeMenu
        function ChangeTimeBinSizeMenuSelected(app, event)
            
        end

        % Value changed function: ChannelSelectionforPlottingEditField
        function ChannelSelectionforPlottingEditFieldValueChanged(app, event)
            [TempData,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Internal_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.ClustertoshowDropDown.Value,app.numCluster,app.Mainapp.CurrentPlotData,app.SpikeBinSettings,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel);
            
            if ~isempty(TempData)
                app.Mainapp.Data = TempData;
                PreviousChannelDeletetion = 0;
                % If Channel were deleted in previous preprocessing flag this
                % to reset main window parameter after + only allow for prepro
                % view if prepro was generated
                if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                    PreviousChannelDeletetion = 1;
                end
                %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                Utility_Show_Info_Loaded_Data(app.Mainapp);
                %% Wrap up preprocessing
                [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                if strcmp(app.Mainapp.DropDown.Value,'Preprocessed Data')
                    if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                        TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                        % Calculate the absolute differences
                        differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                        
                        % Find the index of the minimum difference
                        [~, app.Mainapp.CurrentTimePoints] = min(differences);
                    end
                end
                %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            end
        end

        % Value changed function: TimeWindowSpiketriggredLFPEditField
        function TimeWindowSpiketriggredLFPEditFieldValueChanged(app, event)
            [TempData,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Internal_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.ClustertoshowDropDown.Value,app.numCluster,app.Mainapp.CurrentPlotData,app.SpikeBinSettings,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel);
            
            if ~isempty(TempData)
                app.Mainapp.Data = TempData;
                PreviousChannelDeletetion = 0;
                % If Channel were deleted in previous preprocessing flag this
                % to reset main window parameter after + only allow for prepro
                % view if prepro was generated
                if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                    PreviousChannelDeletetion = 1;
                end
                %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                Utility_Show_Info_Loaded_Data(app.Mainapp);
                %% Wrap up preprocessing
                [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                if strcmp(app.Mainapp.DropDown.Value,'Preprocessed Data')
                    if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                        TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                        % Calculate the absolute differences
                        differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                        
                        % Find the index of the minimum difference
                        [~, app.Mainapp.CurrentTimePoints] = min(differences);
                    end
                end
                %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            end
        end

        % Menu selected function: Show2DimensionalPlotsMenu
        function Show2DimensionalPlotsMenuSelected(app, event)
            app.TwoORThreeD = "TwoD";

            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";
            set(app.UIAxes, 'YDir','reverse');
            set(app.UIAxes,'xticklabel',{[]});

            app.UIAxes.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotBackgroundColor;
            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotFontSize;

            [TempData,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Internal_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.ClustertoshowDropDown.Value,app.numCluster,app.Mainapp.CurrentPlotData,app.SpikeBinSettings,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel);
            
            if ~isempty(TempData)
                app.Mainapp.Data = TempData;
                PreviousChannelDeletetion = 0;
                % If Channel were deleted in previous preprocessing flag this
                % to reset main window parameter after + only allow for prepro
                % view if prepro was generated
                if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                    PreviousChannelDeletetion = 1;
                end
                %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                Utility_Show_Info_Loaded_Data(app.Mainapp);
                %% Wrap up preprocessing
                [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                if strcmp(app.Mainapp.DropDown.Value,'Preprocessed Data')
                    if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                        TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                        % Calculate the absolute differences
                        differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                        
                        % Find the index of the minimum difference
                        [~, app.Mainapp.CurrentTimePoints] = min(differences);
                    end
                end
                %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            end

            %% Plot spike rate -- have to extract spikes again since they have now to be normalized to event time
            %% Bc of Spike triggered avg this has to be computed again (to get spike time stamps not normalized to event time)
            
            [app.Mainapp.Data,Error] = Event_Spikes_Extract_Event_Related_Spikes(app.Mainapp.Data,'Internal',0);
          
            if Error == 1
                return;
            end
        end

        % Value changed function: ClustertoshowDropDown
        function ClustertoshowDropDownValueChanged(app, event)
            % Reset Spike Rate plot in order to show new bin size
            yyaxis(app.UIAxes_3, 'left');
            app.UIAxes_3.NextPlot = "replace"; 
            plot(app.UIAxes_3,0,0);
            app.UIAxes_3.NextPlot = "add";
            set(app.UIAxes_3, 'YDir','reverse');
            app.UIAxes_3.FontSize = 10;
            ylabel(app.UIAxes_3,"Spike Rate [Hz]")

            yyaxis(app.UIAxes_3, 'right');
            app.UIAxes_3.NextPlot = "replace"; 
            plot(app.UIAxes_3,0,0);
            app.UIAxes_3.NextPlot = "add";
            set(app.UIAxes_3, 'YDir','reverse');
            ylabel(app.UIAxes_3,"Spike Rate [Hz]")
            app.UIAxes_3.FontSize = 10;

            app.UIAxes_3.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRTimePlotBackgroundColor;
            app.UIAxes_3.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRTimePlotFontSize;

            app.UIAxes_5.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRChannelPlotBackgroundColor;
            app.UIAxes_5.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRChannelPlotFontSize;
           
            % delete saved unit specific data if no unit selected
            if strcmp(app.ClustertoshowDropDown.Value,"All") || strcmp(app.ClustertoshowDropDown.Value,"Non")
                if isfield(app.Mainapp.CurrentPlotData,'MainRateUnitXData')
                    app.Mainapp.CurrentPlotData = rmfield(app.Mainapp.CurrentPlotData, {'MainRateUnitXData', 'MainRateUnitYData','MainRateUnitCData', 'MainRateUnitType', 'MainRateUnitXTicks'});
                end
                if isfield(app.Mainapp.CurrentPlotData,'MainUnitXData')
                    app.Mainapp.CurrentPlotData = rmfield(app.Mainapp.CurrentPlotData, {'MainUnitXData', 'MainUnitYData','MainUnitCData', 'MainUnitType', 'MainUnitXTicks'});
                end
            end
            
            [TempData,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Internal_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.ClustertoshowDropDown.Value,app.numCluster,app.Mainapp.CurrentPlotData,app.SpikeBinSettings,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel);
            
            if ~isempty(TempData)
                app.Mainapp.Data = TempData;
                PreviousChannelDeletetion = 0;
                % If Channel were deleted in previous preprocessing flag this
                % to reset main window parameter after + only allow for prepro
                % view if prepro was generated
                
                if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                    PreviousChannelDeletetion = 1;
                end

                %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                Utility_Show_Info_Loaded_Data(app.Mainapp);
                %% Wrap up preprocessing
                [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);

                if strcmp(app.Mainapp.DropDown.Value,'Preprocessed Data')
                    if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                        TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                        % Calculate the absolute differences
                        differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                        
                        % Find the index of the minimum difference
                        [~, app.Mainapp.CurrentTimePoints] = min(differences);
                    end
                end
                %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            end
        end

        % Menu selected function: matMenu
        function matMenuSelected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Events Internal All Spikes Spike Rate over Time ",app.AnalysisTypeDropDown.Value));
        end

        % Menu selected function: txtMenu
        function txtMenuSelected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Events Internal All Spikes Spike Rate over Time ",app.AnalysisTypeDropDown.Value));
        end

        % Menu selected function: csvMenu
        function csvMenuSelected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".csv",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Events Internal All Spikes Spike Rate over Time ",app.AnalysisTypeDropDown.Value));
        end

        % Menu selected function: matMenu_2
        function matMenu_2Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Events Internal All Spikes Spike Rate over Channel ",app.AnalysisTypeDropDown.Value));
        end

        % Menu selected function: txtMenu_2
        function txtMenu_2Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;
        
            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Events Internal All Spikes Spike Rate over Channel ",app.AnalysisTypeDropDown.Value));
        end

        % Menu selected function: csvMenu_2
        function csvMenu_2Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".csv",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Events Internal All Spikes Spike Rate over Channel ",app.AnalysisTypeDropDown.Value));
        end

        % Menu selected function: matMenu_3
        function matMenu_3Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Events Internal Spike Rate Unit ",app.ClustertoshowDropDown.Value));
        end

        % Menu selected function: txtMenu_3
        function txtMenu_3Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Events Internal Spike Rate Unit ",app.ClustertoshowDropDown.Value));
        end

        % Menu selected function: csvMenu_3
        function csvMenu_3Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".csv",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Events Internal Spike Rate Unit ",app.ClustertoshowDropDown.Value));
        end

        % Menu selected function: matMenu_4
        function matMenu_4Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Events Internal All Spikes ",app.AnalysisTypeDropDown.Value));
        end

        % Menu selected function: txtMenu_4
        function txtMenu_4Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Events Internal All Spikes ",app.AnalysisTypeDropDown.Value));
        end

        % Menu selected function: csvMenu_4
        function csvMenu_4Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".csv",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Events Internal All Spikes ",app.AnalysisTypeDropDown.Value));
        end

        % Menu selected function: matMenu_5
        function matMenu_5Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Events Internal Unit Spikes ",app.AnalysisTypeDropDown.Value));
        end

        % Menu selected function: txtMenu_5
        function txtMenu_5Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Events Internal Unit Spikes ",app.AnalysisTypeDropDown.Value));
        end

        % Menu selected function: csvMenu_5
        function csvMenu_5Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".csv",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Events Internal Unit Spikes ",app.AnalysisTypeDropDown.Value));
        end

        % Menu selected function: Show3DimensionalPlotsMenu
        function Show3DimensionalPlotsMenuSelected(app, event)
            app.TwoORThreeD = "ThreeD";

            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";
            set(app.UIAxes, 'YDir','reverse');
            set(app.UIAxes,'xticklabel',{[]});

            app.UIAxes.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotBackgroundColor;
            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotFontSize;

            [TempData,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Internal_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.ClustertoshowDropDown.Value,app.numCluster,app.Mainapp.CurrentPlotData,app.SpikeBinSettings,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel);
            
            if ~isempty(TempData)
                app.Mainapp.Data = TempData;
                PreviousChannelDeletetion = 0;
                % If Channel were deleted in previous preprocessing flag this
                % to reset main window parameter after + only allow for prepro
                % view if prepro was generated
                if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                    PreviousChannelDeletetion = 1;
                end
                %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                Utility_Show_Info_Loaded_Data(app.Mainapp);
                %% Wrap up preprocessing
                [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                if strcmp(app.Mainapp.DropDown.Value,'Preprocessed Data')
                    if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                        TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                        % Calculate the absolute differences
                        differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                        
                        % Find the index of the minimum difference
                        [~, app.Mainapp.CurrentTimePoints] = min(differences);
                    end
                end
                %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            end

            %% Plot spike rate -- have to extract spikes again since they have now to be normalized to event time
            %% Bc of Spike triggered avg this has to be computed again (to get spike time stamps not normalized to event time)
            
            [app.Mainapp.Data,Error] = Event_Spikes_Extract_Event_Related_Spikes(app.Mainapp.Data,'Internal',0);
          
            if Error == 1
                return;
            end
        end

        % Menu selected function: ChangeBinSizeTimeMenu
        function ChangeBinSizeTimeMenuSelected(app, event)
            BinSizeChangeWindow(app,"TimeBins");
        end

        % Menu selected function: ChangeBinSizeDepthMenu
        function ChangeBinSizeDepthMenuSelected(app, event)
            BinSizeChangeWindow(app,"DepthBins");
        end

        % Menu selected function: FontSizeMenu
        function FontSizeMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'fontsize',app.Mainapp,"EventSpikeWindowDataFontSize");
            waitfor(h);

            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotFontSize;

            [TempData,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Internal_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.ClustertoshowDropDown.Value,app.numCluster,app.Mainapp.CurrentPlotData,app.SpikeBinSettings,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel);
            
            if ~isempty(TempData)
                app.Mainapp.Data = TempData;
                PreviousChannelDeletetion = 0;
                % If Channel were deleted in previous preprocessing flag this
                % to reset main window parameter after + only allow for prepro
                % view if prepro was generated
                if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                    PreviousChannelDeletetion = 1;
                end
                %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                Utility_Show_Info_Loaded_Data(app.Mainapp);
                %% Wrap up preprocessing
                [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                if strcmp(app.Mainapp.DropDown.Value,'Preprocessed Data')
                    if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                        TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                        % Calculate the absolute differences
                        differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                        
                        % Find the index of the minimum difference
                        [~, app.Mainapp.CurrentTimePoints] = min(differences);
                    end
                end
                %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            end

            %% Plot spike rate -- have to extract spikes again since they have now to be normalized to event time
            %% Bc of Spike triggered avg this has to be computed again (to get spike time stamps not normalized to event time)
            
            [app.Mainapp.Data,Error] = Event_Spikes_Extract_Event_Related_Spikes(app.Mainapp.Data,'Internal',0);
          
            if Error == 1
                return;
            end
        end

        % Menu selected function: ColorSpikesMenu
        function ColorSpikesMenuSelected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotSpikeColor = selectedColor;
            end

            [TempData,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Internal_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.ClustertoshowDropDown.Value,app.numCluster,app.Mainapp.CurrentPlotData,app.SpikeBinSettings,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel);
            
            if ~isempty(TempData)
                app.Mainapp.Data = TempData;
                PreviousChannelDeletetion = 0;
                % If Channel were deleted in previous preprocessing flag this
                % to reset main window parameter after + only allow for prepro
                % view if prepro was generated
                if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                    PreviousChannelDeletetion = 1;
                end
                %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                Utility_Show_Info_Loaded_Data(app.Mainapp);
                %% Wrap up preprocessing
                [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                if strcmp(app.Mainapp.DropDown.Value,'Preprocessed Data')
                    if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                        TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                        % Calculate the absolute differences
                        differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                        
                        % Find the index of the minimum difference
                        [~, app.Mainapp.CurrentTimePoints] = min(differences);
                    end
                end
                %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            end

            %% Plot spike rate -- have to extract spikes again since they have now to be normalized to event time
            %% Bc of Spike triggered avg this has to be computed again (to get spike time stamps not normalized to event time)
            
            [app.Mainapp.Data,Error] = Event_Spikes_Extract_Event_Related_Spikes(app.Mainapp.Data,'Internal',0);
          
            if Error == 1
                return;
            end
        end

        % Menu selected function: ColorTriggerMenu
        function ColorTriggerMenuSelected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotTriggerColor = selectedColor;
            end

            [TempData,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Internal_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.ClustertoshowDropDown.Value,app.numCluster,app.Mainapp.CurrentPlotData,app.SpikeBinSettings,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel);
            
            if ~isempty(TempData)
                app.Mainapp.Data = TempData;
                PreviousChannelDeletetion = 0;
                % If Channel were deleted in previous preprocessing flag this
                % to reset main window parameter after + only allow for prepro
                % view if prepro was generated
                if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                    PreviousChannelDeletetion = 1;
                end
                %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                Utility_Show_Info_Loaded_Data(app.Mainapp);
                %% Wrap up preprocessing
                [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                if strcmp(app.Mainapp.DropDown.Value,'Preprocessed Data')
                    if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                        TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                        % Calculate the absolute differences
                        differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                        
                        % Find the index of the minimum difference
                        [~, app.Mainapp.CurrentTimePoints] = min(differences);
                    end
                end
                %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            end

            %% Plot spike rate -- have to extract spikes again since they have now to be normalized to event time
            %% Bc of Spike triggered avg this has to be computed again (to get spike time stamps not normalized to event time)
            
            [app.Mainapp.Data,Error] = Event_Spikes_Extract_Event_Related_Spikes(app.Mainapp.Data,'Internal',0);
          
            if Error == 1
                return;
            end
        end

        % Menu selected function: BackgorundColorMenu
        function BackgorundColorMenuSelected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotBackgroundColor = selectedColor;
                app.UIAxes.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotBackgroundColor;

            end
        end

        % Menu selected function: LineWidthSpikesMenu_2
        function LineWidthSpikesMenu_2Selected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'linewidth',app.Mainapp,"EventSpikeWindowSpikeLineWidth");
            waitfor(h);

            [TempData,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Internal_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.ClustertoshowDropDown.Value,app.numCluster,app.Mainapp.CurrentPlotData,app.SpikeBinSettings,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel);
            
            if ~isempty(TempData)
                app.Mainapp.Data = TempData;
                PreviousChannelDeletetion = 0;
                % If Channel were deleted in previous preprocessing flag this
                % to reset main window parameter after + only allow for prepro
                % view if prepro was generated
                if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                    PreviousChannelDeletetion = 1;
                end
                %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                Utility_Show_Info_Loaded_Data(app.Mainapp);
                %% Wrap up preprocessing
                [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                if strcmp(app.Mainapp.DropDown.Value,'Preprocessed Data')
                    if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                        TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                        % Calculate the absolute differences
                        differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                        
                        % Find the index of the minimum difference
                        [~, app.Mainapp.CurrentTimePoints] = min(differences);
                    end
                end
                %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            end

            %% Plot spike rate -- have to extract spikes again since they have now to be normalized to event time
            %% Bc of Spike triggered avg this has to be computed again (to get spike time stamps not normalized to event time)
            
            [app.Mainapp.Data,Error] = Event_Spikes_Extract_Event_Related_Spikes(app.Mainapp.Data,'Internal',0);
          
            if Error == 1
                return;
            end
        end

        % Menu selected function: LineWidthTriggerMenu
        function LineWidthTriggerMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'linewidth',app.Mainapp,"EventSpikeWindowTriggerLineWidth");
            waitfor(h);

            [TempData,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Internal_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.ClustertoshowDropDown.Value,app.numCluster,app.Mainapp.CurrentPlotData,app.SpikeBinSettings,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel);
            
            if ~isempty(TempData)
                app.Mainapp.Data = TempData;
                PreviousChannelDeletetion = 0;
                % If Channel were deleted in previous preprocessing flag this
                % to reset main window parameter after + only allow for prepro
                % view if prepro was generated
                if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                    PreviousChannelDeletetion = 1;
                end
                %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                Utility_Show_Info_Loaded_Data(app.Mainapp);
                %% Wrap up preprocessing
                [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                if strcmp(app.Mainapp.DropDown.Value,'Preprocessed Data')
                    if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                        TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                        % Calculate the absolute differences
                        differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                        
                        % Find the index of the minimum difference
                        [~, app.Mainapp.CurrentTimePoints] = min(differences);
                    end
                end
                %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            end

            %% Plot spike rate -- have to extract spikes again since they have now to be normalized to event time
            %% Bc of Spike triggered avg this has to be computed again (to get spike time stamps not normalized to event time)
            
            [app.Mainapp.Data,Error] = Event_Spikes_Extract_Event_Related_Spikes(app.Mainapp.Data,'Internal',0);
          
            if Error == 1
                return;
            end
        end

        % Menu selected function: XLabelMenu
        function XLabelMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'xlabel',app.Mainapp,"EventSpikeWindowSpikeXLabel");
            waitfor(h);

            [TempData,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Internal_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.ClustertoshowDropDown.Value,app.numCluster,app.Mainapp.CurrentPlotData,app.SpikeBinSettings,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel);
            
            if ~isempty(TempData)
                app.Mainapp.Data = TempData;
                PreviousChannelDeletetion = 0;
                % If Channel were deleted in previous preprocessing flag this
                % to reset main window parameter after + only allow for prepro
                % view if prepro was generated
                if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                    PreviousChannelDeletetion = 1;
                end
                %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                Utility_Show_Info_Loaded_Data(app.Mainapp);
                %% Wrap up preprocessing
                [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                if strcmp(app.Mainapp.DropDown.Value,'Preprocessed Data')
                    if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                        TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                        % Calculate the absolute differences
                        differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                        
                        % Find the index of the minimum difference
                        [~, app.Mainapp.CurrentTimePoints] = min(differences);
                    end
                end
                %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            end

            %% Plot spike rate -- have to extract spikes again since they have now to be normalized to event time
            %% Bc of Spike triggered avg this has to be computed again (to get spike time stamps not normalized to event time)
            
            [app.Mainapp.Data,Error] = Event_Spikes_Extract_Event_Related_Spikes(app.Mainapp.Data,'Internal',0);
          
            if Error == 1
                return;
            end
        end

        % Menu selected function: YLabelMenu
        function YLabelMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'ylabel',app.Mainapp,"EventSpikeWindowSpikeYLabel");
            waitfor(h);

            [TempData,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Internal_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.ClustertoshowDropDown.Value,app.numCluster,app.Mainapp.CurrentPlotData,app.SpikeBinSettings,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel);
            
            if ~isempty(TempData)
                app.Mainapp.Data = TempData;
                PreviousChannelDeletetion = 0;
                % If Channel were deleted in previous preprocessing flag this
                % to reset main window parameter after + only allow for prepro
                % view if prepro was generated
                if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                    PreviousChannelDeletetion = 1;
                end
                %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                Utility_Show_Info_Loaded_Data(app.Mainapp);
                %% Wrap up preprocessing
                [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                if strcmp(app.Mainapp.DropDown.Value,'Preprocessed Data')
                    if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                        TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                        % Calculate the absolute differences
                        differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                        
                        % Find the index of the minimum difference
                        [~, app.Mainapp.CurrentTimePoints] = min(differences);
                    end
                end
                %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            end

            %% Plot spike rate -- have to extract spikes again since they have now to be normalized to event time
            %% Bc of Spike triggered avg this has to be computed again (to get spike time stamps not normalized to event time)
            
            [app.Mainapp.Data,Error] = Event_Spikes_Extract_Event_Related_Spikes(app.Mainapp.Data,'Internal',0);
          
            if Error == 1
                return;
            end
        end

        % Menu selected function: RESETMenu
        function RESETMenuSelected(app, event)
            [app.Mainapp.PlotAppearance] = Organize_Set_Standard_PlotAppearance("InternalEventSpikePlot",app.Mainapp.PlotAppearance);

            app.UIAxes.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotBackgroundColor;
            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotFontSize;

            [TempData,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Internal_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.ClustertoshowDropDown.Value,app.numCluster,app.Mainapp.CurrentPlotData,app.SpikeBinSettings,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel);
            
            if ~isempty(TempData)
                app.Mainapp.Data = TempData;
                PreviousChannelDeletetion = 0;
                % If Channel were deleted in previous preprocessing flag this
                % to reset main window parameter after + only allow for prepro
                % view if prepro was generated
                if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                    PreviousChannelDeletetion = 1;
                end
                %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                Utility_Show_Info_Loaded_Data(app.Mainapp);
                %% Wrap up preprocessing
                [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                if strcmp(app.Mainapp.DropDown.Value,'Preprocessed Data')
                    if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                        TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                        % Calculate the absolute differences
                        differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                        
                        % Find the index of the minimum difference
                        [~, app.Mainapp.CurrentTimePoints] = min(differences);
                    end
                end
                %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            end

            %% Plot spike rate -- have to extract spikes again since they have now to be normalized to event time
            %% Bc of Spike triggered avg this has to be computed again (to get spike time stamps not normalized to event time)
            
            [app.Mainapp.Data,Error] = Event_Spikes_Extract_Event_Related_Spikes(app.Mainapp.Data,'Internal',0);
          
            if Error == 1
                return;
            end
        end

        % Menu selected function: FontSizeMenu_3
        function FontSizeMenu_3Selected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes_3,'fontsize',app.Mainapp,"EventSpikeWindowSpikeRateTimeFontSize");
            waitfor(h);

            app.UIAxes_3.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRTimePlotFontSize;
        end

        % Menu selected function: BarColorMenu
        function BarColorMenuSelected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRTimePlotBarColor = selectedColor;
            end

            %% Prepare Plots
            [PlotInfo,SpikeTimes,SpikePositions,~,SpikeCluster,~,~,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value] = Event_Spikes_Prepare_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.SpikeRateNumBinsEditField.Value,"Internal",0,app.TimeWindowSpiketriggredLFPEditField.Value,app.SpikeBinSettings,app.Mainapp.ActiveChannel);
            SpikePositions = (SpikePositions-1)*app.Mainapp.Data.Info.ChannelSpacing;
            app.Mainapp.CurrentPlotData = Event_Spikes_Plot_Spike_Rate(app.Mainapp.Data,PlotInfo.Time,"BinsizeChangeInitial",app.rgbMatrix,SpikeTimes,SpikePositions,SpikeCluster,length(PlotInfo.EventNr(1):PlotInfo.EventNr(2)),"Non",app.SpikeRateNumBinsEditField.Value,app.UIAxes_3,app.UIAxes_5,app.Mainapp.Data.Spikes.ChannelPosition,app.Mainapp.Data.Info.NativeSamplingRate,PlotInfo.ChannelsToPlot,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
       
        end

        % Menu selected function: BackgroundColorMenu_2
        function BackgroundColorMenu_2Selected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRTimePlotBackgroundColor = selectedColor;
                app.UIAxes_3.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRTimePlotBackgroundColor;
            end
        end

        % Menu selected function: XLabelMenu_3
        function XLabelMenu_3Selected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes_3,'xlabel',app.Mainapp,"EventSpikeWindowSpikeRateTimeXLabel");
            waitfor(h);

            %% Prepare Plots
            [PlotInfo,SpikeTimes,SpikePositions,~,SpikeCluster,~,~,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value] = Event_Spikes_Prepare_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.SpikeRateNumBinsEditField.Value,"Internal",0,app.TimeWindowSpiketriggredLFPEditField.Value,app.SpikeBinSettings,app.Mainapp.ActiveChannel);
            SpikePositions = (SpikePositions-1)*app.Mainapp.Data.Info.ChannelSpacing;
            app.Mainapp.CurrentPlotData = Event_Spikes_Plot_Spike_Rate(app.Mainapp.Data,PlotInfo.Time,"BinsizeChangeInitial",app.rgbMatrix,SpikeTimes,SpikePositions,SpikeCluster,length(PlotInfo.EventNr(1):PlotInfo.EventNr(2)),"Non",app.SpikeRateNumBinsEditField.Value,app.UIAxes_3,app.UIAxes_5,app.Mainapp.Data.Spikes.ChannelPosition,app.Mainapp.Data.Info.NativeSamplingRate,PlotInfo.ChannelsToPlot,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
       
        end

        % Menu selected function: YLabelMenu_3
        function YLabelMenu_3Selected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes_3,'fontsize',app.Mainapp,"EventSpikeWindowSpikeRateTimeYLabel");
            waitfor(h);

            %% Prepare Plots
            [PlotInfo,SpikeTimes,SpikePositions,~,SpikeCluster,~,~,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value] = Event_Spikes_Prepare_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.SpikeRateNumBinsEditField.Value,"Internal",0,app.TimeWindowSpiketriggredLFPEditField.Value,app.SpikeBinSettings,app.Mainapp.ActiveChannel);
            SpikePositions = (SpikePositions-1)*app.Mainapp.Data.Info.ChannelSpacing;
            app.Mainapp.CurrentPlotData = Event_Spikes_Plot_Spike_Rate(app.Mainapp.Data,PlotInfo.Time,"BinsizeChangeInitial",app.rgbMatrix,SpikeTimes,SpikePositions,SpikeCluster,length(PlotInfo.EventNr(1):PlotInfo.EventNr(2)),"Non",app.SpikeRateNumBinsEditField.Value,app.UIAxes_3,app.UIAxes_5,app.Mainapp.Data.Spikes.ChannelPosition,app.Mainapp.Data.Info.NativeSamplingRate,PlotInfo.ChannelsToPlot,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
       
        end

        % Menu selected function: RESETMenu_3
        function RESETMenu_3Selected(app, event)
            [app.Mainapp.PlotAppearance] = Organize_Set_Standard_PlotAppearance("InternalEventSpikePlot",app.Mainapp.PlotAppearance);

            app.UIAxes_3.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRTimePlotBackgroundColor;
            app.UIAxes_3.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRTimePlotFontSize ;

            [TempData,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Internal_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.ClustertoshowDropDown.Value,app.numCluster,app.Mainapp.CurrentPlotData,app.SpikeBinSettings,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel);
            
            if ~isempty(TempData)
                app.Mainapp.Data = TempData;
                PreviousChannelDeletetion = 0;
                % If Channel were deleted in previous preprocessing flag this
                % to reset main window parameter after + only allow for prepro
                % view if prepro was generated
                if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                    PreviousChannelDeletetion = 1;
                end
                %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                Utility_Show_Info_Loaded_Data(app.Mainapp);
                %% Wrap up preprocessing
                [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                if strcmp(app.Mainapp.DropDown.Value,'Preprocessed Data')
                    if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                        TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                        % Calculate the absolute differences
                        differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                        
                        % Find the index of the minimum difference
                        [~, app.Mainapp.CurrentTimePoints] = min(differences);
                    end
                end
                %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            end

            %% Plot spike rate -- have to extract spikes again since they have now to be normalized to event time
            %% Bc of Spike triggered avg this has to be computed again (to get spike time stamps not normalized to event time)
            
            [app.Mainapp.Data,Error] = Event_Spikes_Extract_Event_Related_Spikes(app.Mainapp.Data,'Internal',0);
          
            if Error == 1
                return;
            end
        end

        % Menu selected function: FontSizeMenu_5
        function FontSizeMenu_5Selected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes_5,'fontsize',app.Mainapp,"EventSpikeWindowSpikeRateChannelFontSize");
            waitfor(h);

            app.UIAxes_5.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRChannelPlotFontSize;
        end

        % Menu selected function: BarColorMenu_2
        function BarColorMenu_2Selected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRChannelPlotBarColor = selectedColor;
            end

            %% Prepare Plots
            [PlotInfo,SpikeTimes,SpikePositions,~,SpikeCluster,~,~,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value] = Event_Spikes_Prepare_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.SpikeRateNumBinsEditField.Value,"Internal",0,app.TimeWindowSpiketriggredLFPEditField.Value,app.SpikeBinSettings,app.Mainapp.ActiveChannel);
            SpikePositions = (SpikePositions-1)*app.Mainapp.Data.Info.ChannelSpacing;
            app.Mainapp.CurrentPlotData = Event_Spikes_Plot_Spike_Rate(app.Mainapp.Data,PlotInfo.Time,"BinsizeChangeInitial",app.rgbMatrix,SpikeTimes,SpikePositions,SpikeCluster,length(PlotInfo.EventNr(1):PlotInfo.EventNr(2)),"Non",app.SpikeRateNumBinsEditField.Value,app.UIAxes_3,app.UIAxes_5,app.Mainapp.Data.Spikes.ChannelPosition,app.Mainapp.Data.Info.NativeSamplingRate,PlotInfo.ChannelsToPlot,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
       
        end

        % Menu selected function: BackgroundColorMenu_4
        function BackgroundColorMenu_4Selected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRChannelPlotBackgroundColor = selectedColor;
                app.UIAxes_5.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRChannelPlotBackgroundColor;
            end
        end

        % Menu selected function: XLabelMenu_5
        function XLabelMenu_5Selected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes_5,'xlabel',app.Mainapp,"EventSpikeWindowSpikeRateChannelXLabel");
            waitfor(h);

            %% Prepare Plots
            [PlotInfo,SpikeTimes,SpikePositions,~,SpikeCluster,~,~,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value] = Event_Spikes_Prepare_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.SpikeRateNumBinsEditField.Value,"Internal",0,app.TimeWindowSpiketriggredLFPEditField.Value,app.SpikeBinSettings,app.Mainapp.ActiveChannel);
            SpikePositions = (SpikePositions-1)*app.Mainapp.Data.Info.ChannelSpacing;
            app.Mainapp.CurrentPlotData = Event_Spikes_Plot_Spike_Rate(app.Mainapp.Data,PlotInfo.Time,"BinsizeChangeInitial",app.rgbMatrix,SpikeTimes,SpikePositions,SpikeCluster,length(PlotInfo.EventNr(1):PlotInfo.EventNr(2)),"Non",app.SpikeRateNumBinsEditField.Value,app.UIAxes_3,app.UIAxes_5,app.Mainapp.Data.Spikes.ChannelPosition,app.Mainapp.Data.Info.NativeSamplingRate,PlotInfo.ChannelsToPlot,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
       
        end

        % Menu selected function: YLabelMenu_5
        function YLabelMenu_5Selected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes_5,'ylabel',app.Mainapp,"EventSpikeWindowSpikeRateChannelYLabel");
            waitfor(h);

            %% Prepare Plots
            [PlotInfo,SpikeTimes,SpikePositions,~,SpikeCluster,~,~,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value] = Event_Spikes_Prepare_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.SpikeRateNumBinsEditField.Value,"Internal",0,app.TimeWindowSpiketriggredLFPEditField.Value,app.SpikeBinSettings,app.Mainapp.ActiveChannel);
            SpikePositions = (SpikePositions-1)*app.Mainapp.Data.Info.ChannelSpacing;
            app.Mainapp.CurrentPlotData = Event_Spikes_Plot_Spike_Rate(app.Mainapp.Data,PlotInfo.Time,"BinsizeChangeInitial",app.rgbMatrix,SpikeTimes,SpikePositions,SpikeCluster,length(PlotInfo.EventNr(1):PlotInfo.EventNr(2)),"Non",app.SpikeRateNumBinsEditField.Value,app.UIAxes_3,app.UIAxes_5,app.Mainapp.Data.Spikes.ChannelPosition,app.Mainapp.Data.Info.NativeSamplingRate,PlotInfo.ChannelsToPlot,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
       
        end

        % Menu selected function: RESETMenu_5
        function RESETMenu_5Selected(app, event)
            [app.Mainapp.PlotAppearance] = Organize_Set_Standard_PlotAppearance("InternalEventSpikePlot",app.Mainapp.PlotAppearance);

            app.UIAxes_5.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRTimePlotBackgroundColor;
            app.UIAxes_5.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRChannelPlotFontSize ;

            [TempData,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Internal_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.ClustertoshowDropDown.Value,app.numCluster,app.Mainapp.CurrentPlotData,app.SpikeBinSettings,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel);
            
            if ~isempty(TempData)
                app.Mainapp.Data = TempData;
                PreviousChannelDeletetion = 0;
                % If Channel were deleted in previous preprocessing flag this
                % to reset main window parameter after + only allow for prepro
                % view if prepro was generated
                if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                    PreviousChannelDeletetion = 1;
                end
                %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                Utility_Show_Info_Loaded_Data(app.Mainapp);
                %% Wrap up preprocessing
                [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                if strcmp(app.Mainapp.DropDown.Value,'Preprocessed Data')
                    if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                        TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                        % Calculate the absolute differences
                        differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                        
                        % Find the index of the minimum difference
                        [~, app.Mainapp.CurrentTimePoints] = min(differences);
                    end
                end
                %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            end

            %% Plot spike rate -- have to extract spikes again since they have now to be normalized to event time
            %% Bc of Spike triggered avg this has to be computed again (to get spike time stamps not normalized to event time)
            
            [app.Mainapp.Data,Error] = Event_Spikes_Extract_Event_Related_Spikes(app.Mainapp.Data,'Internal',0);
          
            if Error == 1
                return;
            end
        end

        % Menu selected function: SaveCostumeAppearanceMenu
        function SaveCostumeAppearanceMenuSelected(app, event)
            app.Mainapp.PlotAppearance = Utility_Save_Load_Delete_Plot_Appearance(app.Mainapp.PlotAppearance,app.Mainapp.executableFolder,"Save");
        end

        % Menu selected function: DeleteCostumeAppearanceMenu
        function DeleteCostumeAppearanceMenuSelected(app, event)
            app.Mainapp.PlotAppearance = Utility_Save_Load_Delete_Plot_Appearance(app.Mainapp.PlotAppearance,app.Mainapp.executableFolder,"Delete");
        end

        % Close request function: EventRelatedInternalSpikeAnalysisUIFigure
        function EventRelatedInternalSpikeAnalysisUIFigureCloseRequest(app, event)
            app.Mainapp.EventInternalSpikesWindow = [];

            if ~isempty(app.Mainapp.ProbeViewWindowHandle) % delete option from probe view 
                for i = 1:length(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                    if strcmp(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Event Internal Spikes')
                        app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items(i) = [];
                        break;
                    end
                end
            else % if no probe view available
 
            end

            delete(app)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.EventRelatedInternalSpikeAnalysisUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {866, 866};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EventRelatedInternalSpikeAnalysisUIFigure = uifigure('Visible', 'off');
            app.EventRelatedInternalSpikeAnalysisUIFigure.Name = 'Event Related Internal Spike Analysis';
            app.EventRelatedInternalSpikeAnalysisUIFigure.Icon = 'Logo.png';
            app.EventRelatedInternalSpikeAnalysisUIFigure.CloseRequestFcn = createCallbackFcn(app, @EventRelatedInternalSpikeAnalysisUIFigureCloseRequest, true);
            app.EventRelatedInternalSpikeAnalysisUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.SnapshotMenu = uimenu(app.EventRelatedInternalSpikeAnalysisUIFigure);
            app.SnapshotMenu.Text = '   Snapshot   ';
            app.SaveSnapshotofWindowMenu = uimenu(app.SnapshotMenu);
            app.SaveSnapshotofWindowMenu.Text = 'Save Snapshot of Window';
            app.PNGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.PNGMenu.MenuSelectedFcn = createCallbackFcn(app, @PNGMenuSelected, true);
            app.PNGMenu.Text = 'PNG';
            app.JPGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.JPGMenu.MenuSelectedFcn = createCallbackFcn(app, @JPGMenuSelected, true);
            app.JPGMenu.Text = 'JPG';
            app.PlotMenu = uimenu(app.EventRelatedInternalSpikeAnalysisUIFigure);
            app.PlotMenu.Text = '   Plot   ';
            app.Show2DimensionalPlotsMenu = uimenu(app.PlotMenu);
            app.Show2DimensionalPlotsMenu.MenuSelectedFcn = createCallbackFcn(app, @Show2DimensionalPlotsMenuSelected, true);
            app.Show2DimensionalPlotsMenu.Text = 'Show 2 Dimensional Plots';
            app.Show3DimensionalPlotsMenu = uimenu(app.PlotMenu);
            app.Show3DimensionalPlotsMenu.MenuSelectedFcn = createCallbackFcn(app, @Show3DimensionalPlotsMenuSelected, true);
            app.Show3DimensionalPlotsMenu.Text = 'Show 3 Dimensional Plots';
            app.ChangePlotPropertiesMenu = uimenu(app.PlotMenu);
            app.ChangePlotPropertiesMenu.Text = 'Change Plot Properties';
            app.SpikeDataPlotMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.SpikeDataPlotMenu.Text = 'Spike Data Plot';
            app.FontSizeMenu = uimenu(app.SpikeDataPlotMenu);
            app.FontSizeMenu.MenuSelectedFcn = createCallbackFcn(app, @FontSizeMenuSelected, true);
            app.FontSizeMenu.Text = 'FontSize';
            app.LineWidthSpikesMenu_2 = uimenu(app.SpikeDataPlotMenu);
            app.LineWidthSpikesMenu_2.MenuSelectedFcn = createCallbackFcn(app, @LineWidthSpikesMenu_2Selected, true);
            app.LineWidthSpikesMenu_2.Text = 'LineWidth Spikes';
            app.LineWidthTriggerMenu = uimenu(app.SpikeDataPlotMenu);
            app.LineWidthTriggerMenu.MenuSelectedFcn = createCallbackFcn(app, @LineWidthTriggerMenuSelected, true);
            app.LineWidthTriggerMenu.Text = 'LineWidth Trigger';
            app.ColorSpikesMenu = uimenu(app.SpikeDataPlotMenu);
            app.ColorSpikesMenu.MenuSelectedFcn = createCallbackFcn(app, @ColorSpikesMenuSelected, true);
            app.ColorSpikesMenu.Text = 'Color Spikes';
            app.ColorTriggerMenu = uimenu(app.SpikeDataPlotMenu);
            app.ColorTriggerMenu.MenuSelectedFcn = createCallbackFcn(app, @ColorTriggerMenuSelected, true);
            app.ColorTriggerMenu.Text = 'Color Trigger';
            app.BackgorundColorMenu = uimenu(app.SpikeDataPlotMenu);
            app.BackgorundColorMenu.MenuSelectedFcn = createCallbackFcn(app, @BackgorundColorMenuSelected, true);
            app.BackgorundColorMenu.Text = 'Backgorund Color';
            app.XLabelMenu = uimenu(app.SpikeDataPlotMenu);
            app.XLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @XLabelMenuSelected, true);
            app.XLabelMenu.Text = 'XLabel';
            app.YLabelMenu = uimenu(app.SpikeDataPlotMenu);
            app.YLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @YLabelMenuSelected, true);
            app.YLabelMenu.Text = 'YLabel';
            app.RESETMenu = uimenu(app.SpikeDataPlotMenu);
            app.RESETMenu.MenuSelectedFcn = createCallbackFcn(app, @RESETMenuSelected, true);
            app.RESETMenu.Text = 'RESET';
            app.SpikeRatePlotMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.SpikeRatePlotMenu.Text = 'Spike Rate Plot';
            app.SpikeRateoverTimeMenu = uimenu(app.SpikeRatePlotMenu);
            app.SpikeRateoverTimeMenu.Text = 'Spike Rate over Time';
            app.FontSizeMenu_3 = uimenu(app.SpikeRateoverTimeMenu);
            app.FontSizeMenu_3.MenuSelectedFcn = createCallbackFcn(app, @FontSizeMenu_3Selected, true);
            app.FontSizeMenu_3.Text = 'FontSize';
            app.BarColorMenu = uimenu(app.SpikeRateoverTimeMenu);
            app.BarColorMenu.MenuSelectedFcn = createCallbackFcn(app, @BarColorMenuSelected, true);
            app.BarColorMenu.Text = 'Bar Color';
            app.BackgroundColorMenu_2 = uimenu(app.SpikeRateoverTimeMenu);
            app.BackgroundColorMenu_2.MenuSelectedFcn = createCallbackFcn(app, @BackgroundColorMenu_2Selected, true);
            app.BackgroundColorMenu_2.Text = 'Background Color';
            app.XLabelMenu_3 = uimenu(app.SpikeRateoverTimeMenu);
            app.XLabelMenu_3.MenuSelectedFcn = createCallbackFcn(app, @XLabelMenu_3Selected, true);
            app.XLabelMenu_3.Text = 'XLabel';
            app.YLabelMenu_3 = uimenu(app.SpikeRateoverTimeMenu);
            app.YLabelMenu_3.MenuSelectedFcn = createCallbackFcn(app, @YLabelMenu_3Selected, true);
            app.YLabelMenu_3.Text = 'YLabel';
            app.RESETMenu_3 = uimenu(app.SpikeRateoverTimeMenu);
            app.RESETMenu_3.MenuSelectedFcn = createCallbackFcn(app, @RESETMenu_3Selected, true);
            app.RESETMenu_3.Text = 'RESET';
            app.SpikeRateoverDepthMenu_2 = uimenu(app.SpikeRatePlotMenu);
            app.SpikeRateoverDepthMenu_2.Text = 'Spike Rate over Depth';
            app.FontSizeMenu_5 = uimenu(app.SpikeRateoverDepthMenu_2);
            app.FontSizeMenu_5.MenuSelectedFcn = createCallbackFcn(app, @FontSizeMenu_5Selected, true);
            app.FontSizeMenu_5.Text = 'FontSize';
            app.BarColorMenu_2 = uimenu(app.SpikeRateoverDepthMenu_2);
            app.BarColorMenu_2.MenuSelectedFcn = createCallbackFcn(app, @BarColorMenu_2Selected, true);
            app.BarColorMenu_2.Text = 'Bar Color';
            app.BackgroundColorMenu_4 = uimenu(app.SpikeRateoverDepthMenu_2);
            app.BackgroundColorMenu_4.MenuSelectedFcn = createCallbackFcn(app, @BackgroundColorMenu_4Selected, true);
            app.BackgroundColorMenu_4.Text = 'Background Color';
            app.XLabelMenu_5 = uimenu(app.SpikeRateoverDepthMenu_2);
            app.XLabelMenu_5.MenuSelectedFcn = createCallbackFcn(app, @XLabelMenu_5Selected, true);
            app.XLabelMenu_5.Text = 'XLabel';
            app.YLabelMenu_5 = uimenu(app.SpikeRateoverDepthMenu_2);
            app.YLabelMenu_5.MenuSelectedFcn = createCallbackFcn(app, @YLabelMenu_5Selected, true);
            app.YLabelMenu_5.Text = 'YLabel';
            app.RESETMenu_5 = uimenu(app.SpikeRateoverDepthMenu_2);
            app.RESETMenu_5.MenuSelectedFcn = createCallbackFcn(app, @RESETMenu_5Selected, true);
            app.RESETMenu_5.Text = 'RESET';
            app.SaveCostumeAppearanceMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.SaveCostumeAppearanceMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveCostumeAppearanceMenuSelected, true);
            app.SaveCostumeAppearanceMenu.Text = 'Save Costume Appearance';
            app.DeleteCostumeAppearanceMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.DeleteCostumeAppearanceMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteCostumeAppearanceMenuSelected, true);
            app.DeleteCostumeAppearanceMenu.Text = 'Delete Costume Appearance';
            app.HeatmapBinSizeMenu = uimenu(app.PlotMenu);
            app.HeatmapBinSizeMenu.Text = 'Heatmap Bin Size';
            app.ChangeBinSizeTimeMenu = uimenu(app.HeatmapBinSizeMenu);
            app.ChangeBinSizeTimeMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeBinSizeTimeMenuSelected, true);
            app.ChangeBinSizeTimeMenu.Text = 'Change Bin Size Time';
            app.ChangeBinSizeDepthMenu = uimenu(app.HeatmapBinSizeMenu);
            app.ChangeBinSizeDepthMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeBinSizeDepthMenuSelected, true);
            app.ChangeBinSizeDepthMenu.Text = 'Change Bin Size Depth';
            app.ExportDataMenu = uimenu(app.EventRelatedInternalSpikeAnalysisUIFigure);
            app.ExportDataMenu.Text = '   Export Data   ';
            app.SpikeRateMenu_2 = uimenu(app.ExportDataMenu);
            app.SpikeRateMenu_2.Text = 'Spike Rate';
            app.AllSpikesMenu = uimenu(app.SpikeRateMenu_2);
            app.AllSpikesMenu.Text = 'All Spikes';
            app.OverTimeMenu = uimenu(app.AllSpikesMenu);
            app.OverTimeMenu.Text = 'Over Time';
            app.matMenu = uimenu(app.OverTimeMenu);
            app.matMenu.MenuSelectedFcn = createCallbackFcn(app, @matMenuSelected, true);
            app.matMenu.Text = '.mat';
            app.txtMenu = uimenu(app.OverTimeMenu);
            app.txtMenu.MenuSelectedFcn = createCallbackFcn(app, @txtMenuSelected, true);
            app.txtMenu.Text = '.txt';
            app.csvMenu = uimenu(app.OverTimeMenu);
            app.csvMenu.MenuSelectedFcn = createCallbackFcn(app, @csvMenuSelected, true);
            app.csvMenu.Text = '.csv';
            app.OverChannelMenu = uimenu(app.AllSpikesMenu);
            app.OverChannelMenu.Text = 'Over Channel';
            app.matMenu_2 = uimenu(app.OverChannelMenu);
            app.matMenu_2.MenuSelectedFcn = createCallbackFcn(app, @matMenu_2Selected, true);
            app.matMenu_2.Text = '.mat';
            app.txtMenu_2 = uimenu(app.OverChannelMenu);
            app.txtMenu_2.MenuSelectedFcn = createCallbackFcn(app, @txtMenu_2Selected, true);
            app.txtMenu_2.Text = '.txt';
            app.csvMenu_2 = uimenu(app.OverChannelMenu);
            app.csvMenu_2.MenuSelectedFcn = createCallbackFcn(app, @csvMenu_2Selected, true);
            app.csvMenu_2.Text = '.csv';
            app.SelectedUnitMenu = uimenu(app.SpikeRateMenu_2);
            app.SelectedUnitMenu.Text = 'Selected Unit';
            app.matMenu_3 = uimenu(app.SelectedUnitMenu);
            app.matMenu_3.MenuSelectedFcn = createCallbackFcn(app, @matMenu_3Selected, true);
            app.matMenu_3.Text = '.mat';
            app.txtMenu_3 = uimenu(app.SelectedUnitMenu);
            app.txtMenu_3.MenuSelectedFcn = createCallbackFcn(app, @txtMenu_3Selected, true);
            app.txtMenu_3.Text = '.txt';
            app.csvMenu_3 = uimenu(app.SelectedUnitMenu);
            app.csvMenu_3.MenuSelectedFcn = createCallbackFcn(app, @csvMenu_3Selected, true);
            app.csvMenu_3.Text = '.csv';
            app.MainPlotMenu = uimenu(app.ExportDataMenu);
            app.MainPlotMenu.Text = 'Main Plot';
            app.AllSpikesMenu_2 = uimenu(app.MainPlotMenu);
            app.AllSpikesMenu_2.Text = 'All Spikes';
            app.matMenu_4 = uimenu(app.AllSpikesMenu_2);
            app.matMenu_4.MenuSelectedFcn = createCallbackFcn(app, @matMenu_4Selected, true);
            app.matMenu_4.Text = '.mat';
            app.txtMenu_4 = uimenu(app.AllSpikesMenu_2);
            app.txtMenu_4.MenuSelectedFcn = createCallbackFcn(app, @txtMenu_4Selected, true);
            app.txtMenu_4.Text = '.txt';
            app.csvMenu_4 = uimenu(app.AllSpikesMenu_2);
            app.csvMenu_4.MenuSelectedFcn = createCallbackFcn(app, @csvMenu_4Selected, true);
            app.csvMenu_4.Text = '.csv';
            app.SelectedUnitMenu_2 = uimenu(app.MainPlotMenu);
            app.SelectedUnitMenu_2.Text = 'Selected Unit';
            app.matMenu_5 = uimenu(app.SelectedUnitMenu_2);
            app.matMenu_5.MenuSelectedFcn = createCallbackFcn(app, @matMenu_5Selected, true);
            app.matMenu_5.Text = '.mat';
            app.txtMenu_5 = uimenu(app.SelectedUnitMenu_2);
            app.txtMenu_5.MenuSelectedFcn = createCallbackFcn(app, @txtMenu_5Selected, true);
            app.txtMenu_5.Text = '.txt';
            app.csvMenu_5 = uimenu(app.SelectedUnitMenu_2);
            app.csvMenu_5.MenuSelectedFcn = createCallbackFcn(app, @csvMenu_5Selected, true);
            app.csvMenu_5.Text = '.csv';
            app.GridLayout = uigridlayout(app.EventRelatedInternalSpikeAnalysisUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes_3 = uiaxes(app.RightPanel);
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes_5 = uiaxes(app.RightPanel);
            xlabel(app.UIAxes_5, 'X')
            zlabel(app.UIAxes_5, 'Z')
            app.SpikeAnalysisOptionsPanel = uipanel(app.RightPanel);
            app.SpikeAnalysisOptionsPanel.Title = 'Spike Analysis Options';
            app.SpikeRateNumBinsEditFieldLabel = uilabel(app.SpikeAnalysisOptionsPanel);
            app.SpikeRateNumBinsEditFieldLabel.Text = {'Spike Rate'; 'Num Bins'};
            app.SpikeRateNumBinsEditField = uieditfield(app.SpikeAnalysisOptionsPanel, 'text');
            app.SpikeRateNumBinsEditField.ValueChangedFcn = createCallbackFcn(app, @SpikeRateNumBinsEditFieldValueChanged, true);
            app.SpikeRateNumBinsEditField.Value = '100';
            app.EventRangeEditFieldLabel = uilabel(app.SpikeAnalysisOptionsPanel);
            app.EventRangeEditFieldLabel.Text = 'Event Range';
            app.EventRangeEditField = uieditfield(app.SpikeAnalysisOptionsPanel, 'text');
            app.EventRangeEditField.ValueChangedFcn = createCallbackFcn(app, @EventRangeEditFieldValueChanged, true);
            app.AnalysisTypeDropDownLabel = uilabel(app.SpikeAnalysisOptionsPanel);
            app.AnalysisTypeDropDownLabel.Text = 'Analysis Type';
            app.AnalysisTypeDropDown = uidropdown(app.SpikeAnalysisOptionsPanel);
            app.AnalysisTypeDropDown.Items = {'Spike Map', 'Spike Rate Heatmap', 'Spike Triggered Average'};
            app.AnalysisTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @AnalysisTypeDropDownValueChanged, true);
            app.AnalysisTypeDropDown.Value = 'Spike Map';
            app.BaselineNormalizeCheckBox = uicheckbox(app.SpikeAnalysisOptionsPanel);
            app.BaselineNormalizeCheckBox.ValueChangedFcn = createCallbackFcn(app, @BaselineNormalizeCheckBoxValueChanged, true);
            app.BaselineNormalizeCheckBox.Text = '  Baseline Normalize';
            app.BaselineWindowStartStopinsEditFieldLabel = uilabel(app.SpikeAnalysisOptionsPanel);
            app.BaselineWindowStartStopinsEditFieldLabel.Text = {'Baseline Window'; '[Start,Stop] in s'};
            app.BaselineWindowStartStopinsEditField = uieditfield(app.SpikeAnalysisOptionsPanel, 'text');
            app.BaselineWindowStartStopinsEditField.ValueChangedFcn = createCallbackFcn(app, @BaselineWindowStartStopinsEditFieldValueChanged, true);
            app.ChannelSelectionforPlottingEditFieldLabel = uilabel(app.SpikeAnalysisOptionsPanel);
            app.ChannelSelectionforPlottingEditFieldLabel.Enable = 'off';
            app.ChannelSelectionforPlottingEditFieldLabel.Visible = 'off';
            app.ChannelSelectionforPlottingEditFieldLabel.Text = {'Channel Selection'; 'for Plotting'};
            app.ChannelSelectionforPlottingEditField = uieditfield(app.SpikeAnalysisOptionsPanel, 'text');
            app.ChannelSelectionforPlottingEditField.ValueChangedFcn = createCallbackFcn(app, @ChannelSelectionforPlottingEditFieldValueChanged, true);
            app.ChannelSelectionforPlottingEditField.Editable = 'off';
            app.ChannelSelectionforPlottingEditField.Enable = 'off';
            app.ChannelSelectionforPlottingEditField.Visible = 'off';
            app.TimeWindowSpiketriggredLFPEditFieldLabel = uilabel(app.SpikeAnalysisOptionsPanel);
            app.TimeWindowSpiketriggredLFPEditFieldLabel.Text = {'Time Window '; 'Spike triggred LFP'};
            app.TimeWindowSpiketriggredLFPEditField = uieditfield(app.SpikeAnalysisOptionsPanel, 'text');
            app.TimeWindowSpiketriggredLFPEditField.ValueChangedFcn = createCallbackFcn(app, @TimeWindowSpiketriggredLFPEditFieldValueChanged, true);
            app.TimeWindowSpiketriggredLFPEditField.Value = '-0.005,0.2';
            app.ClustertoShowDropDownLabel = uilabel(app.SpikeAnalysisOptionsPanel);
            app.ClustertoShowDropDownLabel.Text = 'Cluster to Show';
            app.ClustertoshowDropDown = uidropdown(app.SpikeAnalysisOptionsPanel);
            app.ClustertoshowDropDown.Items = {};
            app.ClustertoshowDropDown.ValueChangedFcn = createCallbackFcn(app, @ClustertoshowDropDownValueChanged, true);
            app.ClustertoshowDropDown.Value = {};
            app.TextArea = uitextarea(app.RightPanel);
            app.InformationLabel = uilabel(app.RightPanel);
            app.InformationLabel.Text = 'Information';
            app.EventRelatedInternalSpikeAnalysisLabel = uilabel(app.RightPanel);
            app.EventRelatedInternalSpikeAnalysisLabel.Text = 'Event Related Internal Spike Analysis';
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodLabel = uilabel(app.RightPanel);
            app.NeuromodLabel.Text = 'Neuromod';
            app.CloseButton = uibutton(app.RightPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.ContextMenu3 = uicontextmenu(app.EventRelatedInternalSpikeAnalysisUIFigure);
            app.ChangeTimeBinSizeMenu = uimenu(app.ContextMenu3);
            app.ChangeTimeBinSizeMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeTimeBinSizeMenuSelected, true);
            app.ChangeTimeBinSizeMenu.Text = 'Change Time Bin Size';
            % Assign app.ContextMenu3
            app.UIAxes.ContextMenu = app.ContextMenu3;
            % Show the figure after all components are created
            app.EventRelatedInternalSpikeAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Events_Internal_Spike_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.EventRelatedInternalSpikeAnalysisUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.EventRelatedInternalSpikeAnalysisUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EventRelatedInternalSpikeAnalysisUIFigure)
        end
    end
end