classdef SpikeTrgAvgAskPrepro < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PreproSTAWindowUIFigure    matlab.ui.Figure
        LowPassFilterSettingsMenu  matlab.ui.container.Menu
        SetFilterOrderMenu         matlab.ui.container.Menu
        SetCutoffFrequencyMenu     matlab.ui.container.Menu
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        RightPanel                 matlab.ui.container.Panel
        TextArea                   matlab.ui.control.TextArea
        ProceedButton              matlab.ui.control.Button
        Image                      matlab.ui.control.Image
        PreproSTAWindowLabel       matlab.ui.control.Label
        CloseButton                matlab.ui.control.Button
        NeuromodLabel              matlab.ui.control.Label
        FilterOptionsPanel         matlab.ui.container.Panel
        DownsampleCheckBox         matlab.ui.control.CheckBox
        LowPassFilterCheckBox      matlab.ui.control.CheckBox
        WarningDatawasnotlowdownsampledDonowwithstandardsettingsLabel  matlab.ui.control.Label
        WarningDatawasnotlowpassedfilteredLabel  matlab.ui.control.Label
        SaveasnewPreprocessedDatasetDropDown  matlab.ui.control.DropDown
        SaveasnewPreprocessedDatasetDropDownLabel  matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        DatatoUse = []; % Description
        Downsampled = [];
        LowPass = [];
        SpiketrgAvgPrepro = [];
        FilterSettings = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, DatatoUse, Downsampled, LowPass)
            movegui(app.PreproSTAWindowUIFigure, 'center');

            app.DatatoUse = DatatoUse; % Description
            app.Downsampled = Downsampled;
            app.LowPass = LowPass;

            app.FilterSettings.Cutoff = '220';
            app.FilterSettings.FilterOrder = '6';
            
            if strcmp(DatatoUse,'Preprocessed')
                if app.LowPass == 1
                    app.LowPassFilterCheckBox.Enable = "off";
                    app.WarningDatawasnotlowpassedfilteredLabel.Text = "Data is already low pass filtered";
                else
                    app.LowPassFilterCheckBox.Enable = "on";
                end
            else
                app.LowPassFilterCheckBox.Enable = "on";
            end

            if Downsampled == 1
                app.DownsampleCheckBox.Enable = "off";
                app.WarningDatawasnotlowdownsampledDonowwithstandardsettingsLabel.Text = "Data is already downsampled";
            else
                app.DownsampleCheckBox.Enable = "on";
            end
        end

        % Button pushed function: ProceedButton
        function ProceedButtonPushed(app, event)

            if app.LowPassFilterCheckBox.Value == 1
                app.SpiketrgAvgPrepro.LowPass = 1;
            else
                app.SpiketrgAvgPrepro.LowPass = 0;
            end

            if app.DownsampleCheckBox.Value == 1
                app.SpiketrgAvgPrepro.Downsample = 1;
            else
                app.SpiketrgAvgPrepro.Downsample = 0;
            end

            app.SpiketrgAvgPrepro.SavePreproData = app.SaveasnewPreprocessedDatasetDropDown.Value;
           
            % Resume execution to return the output
            uiresume(app.PreproSTAWindowUIFigure);
           
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app)
        end

        % Menu selected function: SetFilterOrderMenu
        function SetFilterOrderMenuSelected(app, event)
            g = Set_LowPassFilterSettings(app,"FilterOrder");
            waitfor(g);

            msgbox("Succesfully changed filter order to ",app.FilterSettings.FilterOrder)
        end

        % Menu selected function: SetCutoffFrequencyMenu
        function SetCutoffFrequencyMenuSelected(app, event)
            g = Set_LowPassFilterSettings(app,"Cutoff");
            waitfor(g);

            msgbox("Succesfully changed cutoff frequency to ",app.FilterSettings.Cutoff)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.PreproSTAWindowUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {341, 341};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PreproSTAWindowUIFigure = uifigure('Visible', 'off');
            app.PreproSTAWindowUIFigure.Name = 'Prepro STA Window';
            app.PreproSTAWindowUIFigure.Icon = 'Logo.png';
            app.PreproSTAWindowUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.LowPassFilterSettingsMenu = uimenu(app.PreproSTAWindowUIFigure);
            app.LowPassFilterSettingsMenu.Text = '   Low Pass Filter Settings   ';
            app.SetFilterOrderMenu = uimenu(app.LowPassFilterSettingsMenu);
            app.SetFilterOrderMenu.MenuSelectedFcn = createCallbackFcn(app, @SetFilterOrderMenuSelected, true);
            app.SetFilterOrderMenu.Text = 'Set Filter Order';
            app.SetCutoffFrequencyMenu = uimenu(app.LowPassFilterSettingsMenu);
            app.SetCutoffFrequencyMenu.MenuSelectedFcn = createCallbackFcn(app, @SetCutoffFrequencyMenuSelected, true);
            app.SetCutoffFrequencyMenu.Text = 'Set Cutoff Frequency';
            app.GridLayout = uigridlayout(app.PreproSTAWindowUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.FilterOptionsPanel = uipanel(app.RightPanel);
            app.FilterOptionsPanel.Title = 'Filter Options';
            app.SaveasnewPreprocessedDatasetDropDownLabel = uilabel(app.FilterOptionsPanel);
            app.SaveasnewPreprocessedDatasetDropDownLabel.Text = {'Save as new '; 'Preprocessed Dataset'};
            app.SaveasnewPreprocessedDatasetDropDown = uidropdown(app.FilterOptionsPanel);
            app.SaveasnewPreprocessedDatasetDropDown.Items = {'No', 'Yes'};
            app.SaveasnewPreprocessedDatasetDropDown.Value = 'No';
            app.WarningDatawasnotlowpassedfilteredLabel = uilabel(app.FilterOptionsPanel);
            app.WarningDatawasnotlowpassedfilteredLabel.Text = {'Warning: Data was not low passed filtered!'; 'Do now with standard settings?'};
            app.WarningDatawasnotlowdownsampledDonowwithstandardsettingsLabel = uilabel(app.FilterOptionsPanel);
            app.WarningDatawasnotlowdownsampledDonowwithstandardsettingsLabel.Text = {'Warning: Data was not low downsampled!'; 'Do now with standard settings?'};
            app.LowPassFilterCheckBox = uicheckbox(app.FilterOptionsPanel);
            app.LowPassFilterCheckBox.Text = '  Low Pass Filter';
            app.DownsampleCheckBox = uicheckbox(app.FilterOptionsPanel);
            app.DownsampleCheckBox.Text = '    Downsample';
            app.NeuromodLabel = uilabel(app.RightPanel);
            app.NeuromodLabel.Text = 'Neuromod';
            app.CloseButton = uibutton(app.RightPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.PreproSTAWindowLabel = uilabel(app.RightPanel);
            app.PreproSTAWindowLabel.Text = 'Prepro STA Window';
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = 'Logo.png';
            app.ProceedButton = uibutton(app.RightPanel, 'push');
            app.ProceedButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedButtonPushed, true);
            app.ProceedButton.Text = 'Proceed';
            app.TextArea = uitextarea(app.RightPanel);
            app.TextArea.Value = {'Warning:'; ''; 'Data to compute spike triggered average with is not low pass filtered and/or downsampled.'; 'Select at least low pass filter to compute spike triggered LFP.'; 'Not downsampling can take very long and requires a lot of memory depending on the number of spikes.'; ''; 'Standard Filter Settings (can be changed in the menu above):'; 'Filter Order: 6'; 'Cutoff Frequency: 220 Hz'};
            % Show the figure after all components are created
            app.PreproSTAWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpikeTrgAvgAskPrepro(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.PreproSTAWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.PreproSTAWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PreproSTAWindowUIFigure)
        end
    end
end