classdef Events_Internal_Spike_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Event_Related_InternalSpikeAnalysisUIFigure  matlab.ui.Figure
        SnapshotMenu                    matlab.ui.container.Menu
        SaveSnapshotofMainWindowMenu    matlab.ui.container.Menu
        PNGMenu                         matlab.ui.container.Menu
        JPGMenu                         matlab.ui.container.Menu
        CloseButton                     matlab.ui.control.Button
        NeuromodToolboxLabel            matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        EventRelatedInternalSpikesWindowLabel  matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        ChannelSelectionforPlottingEditField  matlab.ui.control.EditField
        ChannelSelectionforPlottingEditFieldLabel  matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        BaselineWindowStartStopinsEditField  matlab.ui.control.EditField
        BaselineWindowStartStopinsEditFieldLabel  matlab.ui.control.Label
        BaselineNormalizeCheckBox       matlab.ui.control.CheckBox
        AnalysisTypeDropDown            matlab.ui.control.DropDown
        AnalysisTypeDropDownLabel       matlab.ui.control.Label
        EventRangeEditField             matlab.ui.control.EditField
        EventRangeEditFieldLabel        matlab.ui.control.Label
        SpikeRateNumBinsEditField       matlab.ui.control.EditField
        SpikeRateNumBinsEditFieldLabel  matlab.ui.control.Label
        UIAxes_5                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        ContextMenu3                    matlab.ui.container.ContextMenu
        ChangeTimeBinSizeMenu           matlab.ui.container.Menu
        ChangeTitleMenu                 matlab.ui.container.Menu
        ChangeXLabelMenu                matlab.ui.container.Menu
        ChangeYLabelMenu                matlab.ui.container.Menu
        ChangeXlimMenu                  matlab.ui.container.Menu
        ChangeYlimMenu                  matlab.ui.container.Menu
        ChangeLegendMenu                matlab.ui.container.Menu
        ChangeFontsizeMenu              matlab.ui.container.Menu
        ContextMenu3_2                  matlab.ui.container.ContextMenu
        ChangeTitleMenu_2               matlab.ui.container.Menu
        ChangeXLabelMenu_2              matlab.ui.container.Menu
        ChangeYLabelMenu_2              matlab.ui.container.Menu
        ChangeXlimMenu_2                matlab.ui.container.Menu
        ChangeYlimMenu_2                matlab.ui.container.Menu
        ChangeLegendMenu_2              matlab.ui.container.Menu
        ChangeFontsizeMenu_2            matlab.ui.container.Menu
        ContextMenu3_3                  matlab.ui.container.ContextMenu
        ChangeTitleMenu_3               matlab.ui.container.Menu
        ChangeXLabelMenu_3              matlab.ui.container.Menu
        ChangeYLabelMenu_3              matlab.ui.container.Menu
        ChangeXlimMenu_3                matlab.ui.container.Menu
        ChangeYlimMenu_3                matlab.ui.container.Menu
        ChangeLegendMenu_3              matlab.ui.container.Menu
        ChangeFontsizeMenu_3            matlab.ui.container.Menu
    end

    
    properties (Access = public)
        Mainapp = []; % Description
        Settings = [];
        SpikeData =[];
        SpikeBinSettings = [];
        rgbMatrix = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            app.Mainapp = Mainapp;

            movegui(app.Event_Related_InternalSpikeAnalysisUIFigure, 'center');
            
            [app] = Spikes_Set_Up_Spike_Analysis_Windows(app,[],"EventWindow");

            %% Prepare Plots
            [PlotInfo,SpikeTimes,SpikePositions,SpikeAmplitude,SpikeCluster,~,~,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value] = Event_Spikes_Prepare_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.SpikeRateNumBinsEditField.Value,"Internal");

            if strcmp(app.AnalysisTypeDropDown.Value,"Spike Map")
                Spikes_Plot_Spike_Times("Eventrelated",app.rgbMatrix,PlotInfo.Time,SpikeTimes,SpikePositions,SpikeCluster,SpikeAmplitude,app.Mainapp.Data.Spikes.ChannelPosition,app.UIAxes,[],"Non",[],[],PlotInfo.ChannelsToPlot,app.Mainapp.Data.Info.ChannelSpacing)
                Event_Spikes_Plot_Spike_Rate(app.Mainapp.Data,PlotInfo.Time,"Initial",app.rgbMatrix,SpikeTimes,SpikePositions,SpikeCluster,length(PlotInfo.EventNr(1):PlotInfo.EventNr(2)),"Non",app.SpikeRateNumBinsEditField.Value,app.UIAxes_3,app.UIAxes_5,app.Mainapp.Data.Spikes.ChannelPosition,app.Mainapp.Data.Info.NativeSamplingRate,PlotInfo.ChannelsToPlot)
            elseif strcmp(app.AnalysisTypeDropDown.Value,"Spike Rate Heatmap")
                Event_Spikes_Plot_Spike_Rate(app.Mainapp.Data,PlotInfo.Time,"Initial",app.rgbMatrix,SpikeTimes,SpikePositions,SpikeCluster,length(PlotInfo.EventNr(1):PlotInfo.EventNr(2)),"Non",app.SpikeRateNumBinsEditField.Value,app.UIAxes_3,app.UIAxes_5,app.Mainapp.Data.Spikes.ChannelPosition,app.Mainapp.Data.Info.NativeSamplingRate,PlotInfo.ChannelsToPlot)
            end
            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: SpikeRateNumBinsEditField
        function SpikeRateNumBinsEditFieldValueChanged(app, event)
            app.UIAxes_3.NextPlot = "replace"; 
            plot(app.UIAxes_3,0,0);
            app.UIAxes_3.NextPlot = "add";
            set(app.UIAxes_3, 'YDir','reverse');
            app.UIAxes_3.FontSize = 10;
            ylabel(app.UIAxes_3,"Spike Rate [Hz]")

            %% Prepare Plots
            [PlotInfo,SpikeTimes,SpikePositions,SpikeAmplitude,SpikeCluster,~,~,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value] = Event_Spikes_Prepare_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.SpikeRateNumBinsEditField.Value,"Internal");

            Event_Spikes_Plot_Spike_Rate(app.Mainapp.Data,PlotInfo.Time,"BinsizeChangeInitial",app.rgbMatrix,SpikeTimes,SpikePositions,SpikeCluster,length(PlotInfo.EventNr(1):PlotInfo.EventNr(2)),"Non",app.SpikeRateNumBinsEditField.Value,app.UIAxes_3,app.UIAxes_5,app.Mainapp.Data.Spikes.ChannelPosition,app.Mainapp.Data.Info.NativeSamplingRate,PlotInfo.ChannelsToPlot)

        end

        % Menu selected function: PNGMenu
        function PNGMenuSelected(app, event)
            [file, path] = uiputfile('*.png', 'Save as');

            exportapp(app.Event_Related_InternalSpikeAnalysisUIFigure,fullfile(path,file)) 
        end

        % Menu selected function: JPGMenu
        function JPGMenuSelected(app, event)
            [file, path] = uiputfile('*.jpg', 'Save as');

            exportapp(app.Event_Related_InternalSpikeAnalysisUIFigure,fullfile(path,file)) 
        end

        % Value changed function: EventRangeEditField
        function EventRangeEditFieldValueChanged(app, event)
            app.UIAxes_3.NextPlot = "replace"; 
            plot(app.UIAxes_3,0,0);
            app.UIAxes_3.NextPlot = "add";
            set(app.UIAxes_3, 'YDir','reverse');
            app.UIAxes_3.FontSize = 10;
            ylabel(app.UIAxes_3,"Spike Rate [Hz]")

            %% Prepare Plots
            [PlotInfo,SpikeTimes,SpikePositions,SpikeAmplitude,SpikeCluster,~,~,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value] = Event_Spikes_Prepare_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.SpikeRateNumBinsEditField.Value,"Internal");

            if strcmp(app.AnalysisTypeDropDown.Value,"Spike Map")
                
                Spikes_Plot_Spike_Times("Eventrelated",app.rgbMatrix,PlotInfo.Time,SpikeTimes,SpikePositions,SpikeCluster,SpikeAmplitude,app.Mainapp.Data.Spikes.ChannelPosition,app.UIAxes,[],"Non",[],[],PlotInfo.ChannelsToPlot,app.Mainapp.Data.Info.ChannelSpacing)
                Event_Spikes_Plot_Spike_Rate(app.Mainapp.Data,PlotInfo.Time,"BinsizeChangeInitial",app.rgbMatrix,SpikeTimes,SpikePositions,SpikeCluster,length(PlotInfo.EventNr(1):PlotInfo.EventNr(2)),"Non",app.SpikeRateNumBinsEditField.Value,app.UIAxes_3,app.UIAxes_5,app.Mainapp.Data.Spikes.ChannelPosition,app.Mainapp.Data.Info.NativeSamplingRate,PlotInfo.ChannelsToPlot)

            elseif strcmp(app.AnalysisTypeDropDown.Value,"Spike Rate Heatmap")
                Event_Spikes_Plot_Heatmap_Spike_Rate(SpikeTimes,SpikePositions,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,PlotInfo.time_bin_size,PlotInfo.depth_edges,PlotInfo.time_edges,length(PlotInfo.EventNr(1):PlotInfo.EventNr(2)),0,app.BaselineNormalizeCheckBox.Value,PlotInfo.NormWindow,"Non",SpikeCluster,app.rgbMatrix,PlotInfo.ChannelsToPlot,app.Mainapp.Data.Info.ChannelSpacing,"Internal")
                Event_Spikes_Plot_Spike_Rate(app.Mainapp.Data,PlotInfo.Time,"BinsizeChangeInitial",app.rgbMatrix,SpikeTimes,SpikePositions,SpikeCluster,length(PlotInfo.EventNr(1):PlotInfo.EventNr(2)),"Non",app.SpikeRateNumBinsEditField.Value,app.UIAxes_3,app.UIAxes_5,app.Mainapp.Data.Spikes.ChannelPosition,app.Mainapp.Data.Info.NativeSamplingRate,PlotInfo.ChannelsToPlot)
            end

        end

        % Menu selected function: ChangeTitleMenu
        function ChangeTitleMenuSelected(app, event)
            Figure_Property_Change_Window(app.UIAxes,'title');
        end

        % Menu selected function: ChangeXLabelMenu
        function ChangeXLabelMenuSelected(app, event)
            Figure_Property_Change_Window(app.UIAxes,'xlabel');
        end

        % Menu selected function: ChangeYLabelMenu
        function ChangeYLabelMenuSelected(app, event)
            Figure_Property_Change_Window(app.UIAxes,'ylabel');
        end

        % Menu selected function: ChangeXlimMenu
        function ChangeXlimMenuSelected(app, event)
            Figure_Property_Change_Window(app.UIAxes,'xlim');
        end

        % Menu selected function: ChangeYlimMenu
        function ChangeYlimMenuSelected(app, event)
            Figure_Property_Change_Window(app.UIAxes,'ylim');
        end

        % Menu selected function: ChangeLegendMenu
        function ChangeLegendMenuSelected(app, event)
            Figure_Property_Change_Window(app.UIAxes,'legend');
        end

        % Menu selected function: ChangeFontsizeMenu
        function ChangeFontsizeMenuSelected(app, event)
            
        end

        % Menu selected function: ChangeTitleMenu_2
        function ChangeTitleMenu_2Selected(app, event)
             Figure_Property_Change_Window(app.UIAxes_3,'title');
        end

        % Menu selected function: ChangeXLabelMenu_2
        function ChangeXLabelMenu_2Selected(app, event)
            Figure_Property_Change_Window(app.UIAxes_3,'xlabel');
        end

        % Menu selected function: ChangeYLabelMenu_2
        function ChangeYLabelMenu_2Selected(app, event)
            Figure_Property_Change_Window(app.UIAxes_3,'ylabel');
        end

        % Menu selected function: ChangeXlimMenu_2
        function ChangeXlimMenu_2Selected(app, event)
            Figure_Property_Change_Window(app.UIAxes_3,'xlim');
        end

        % Menu selected function: ChangeYlimMenu_2
        function ChangeYlimMenu_2Selected(app, event)
            Figure_Property_Change_Window(app.UIAxes_3,'ylim');
        end

        % Menu selected function: ChangeLegendMenu_2
        function ChangeLegendMenu_2Selected(app, event)
             Figure_Property_Change_Window(app.UIAxes_3,'legend');
        end

        % Menu selected function: ChangeFontsizeMenu_2
        function ChangeFontsizeMenu_2Selected(app, event)
            
        end

        % Menu selected function: ChangeTitleMenu_3
        function ChangeTitleMenu_3Selected(app, event)
            Figure_Property_Change_Window(app.UIAxes_5,'title');
        end

        % Menu selected function: ChangeXLabelMenu_3
        function ChangeXLabelMenu_3Selected(app, event)
            Figure_Property_Change_Window(app.UIAxes_5,'xlabel');
        end

        % Menu selected function: ChangeYLabelMenu_3
        function ChangeYLabelMenu_3Selected(app, event)
            Figure_Property_Change_Window(app.UIAxes_5,'ylabel');
        end

        % Menu selected function: ChangeXlimMenu_3
        function ChangeXlimMenu_3Selected(app, event)
            Figure_Property_Change_Window(app.UIAxes_5,'xlim');
        end

        % Menu selected function: ChangeYlimMenu_3
        function ChangeYlimMenu_3Selected(app, event)
            Figure_Property_Change_Window(app.UIAxes_5,'ylim');
        end

        % Menu selected function: ChangeLegendMenu_3
        function ChangeLegendMenu_3Selected(app, event)
            Figure_Property_Change_Window(app.UIAxes_5,'legend');
        end

        % Menu selected function: ChangeFontsizeMenu_3
        function ChangeFontsizeMenu_3Selected(app, event)
            
        end

        % Value changed function: AnalysisTypeDropDown
        function AnalysisTypeDropDownValueChanged(app, event)
            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";
            set(app.UIAxes, 'YDir','reverse');
            set(app.UIAxes,'xticklabel',{[]});

            %% Prepare Plots
            [PlotInfo,SpikeTimes,SpikePositions,SpikeAmplitude,SpikeCluster,~,~,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value] = Event_Spikes_Prepare_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.SpikeRateNumBinsEditField.Value,"Internal");

            if strcmp(app.AnalysisTypeDropDown.Value,"Spike Map")
                app.BaselineNormalizeCheckBox.Enable = "off";
                app.BaselineWindowStartStopinsEditField.Enable = "off";
                Spikes_Plot_Spike_Times("Eventrelated",app.rgbMatrix,PlotInfo.Time,SpikeTimes,SpikePositions,SpikeCluster,SpikeAmplitude,app.Mainapp.Data.Spikes.ChannelPosition,app.UIAxes,[],"Non",[],[],PlotInfo.ChannelsToPlot,app.Mainapp.Data.Info.ChannelSpacing)
                Event_Spikes_Plot_Spike_Rate(app.Mainapp.Data,PlotInfo.Time,"BinsizeChangeInitial",app.rgbMatrix,SpikeTimes,SpikePositions,SpikeCluster,length(PlotInfo.EventNr(1):PlotInfo.EventNr(2)),"Non",app.SpikeRateNumBinsEditField.Value,app.UIAxes_3,app.UIAxes_5,app.Mainapp.Data.Spikes.ChannelPosition,app.Mainapp.Data.Info.NativeSamplingRate,PlotInfo.ChannelsToPlot)

            elseif strcmp(app.AnalysisTypeDropDown.Value,"Spike Rate Heatmap")
                app.BaselineNormalizeCheckBox.Enable = "on";
                app.BaselineWindowStartStopinsEditField.Enable = "on";
                Event_Spikes_Plot_Heatmap_Spike_Rate(SpikeTimes,SpikePositions,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,PlotInfo.time_bin_size,PlotInfo.depth_edges,PlotInfo.time_edges,length(PlotInfo.EventNr(1):PlotInfo.EventNr(2)),0,app.BaselineNormalizeCheckBox.Value,PlotInfo.NormWindow,"Non",SpikeCluster,app.rgbMatrix,PlotInfo.ChannelsToPlot,app.Mainapp.Data.Info.ChannelSpacing,"Internal")
                Event_Spikes_Plot_Spike_Rate(app.Mainapp.Data,PlotInfo.Time,"BinsizeChangeInitial",app.rgbMatrix,SpikeTimes,SpikePositions,SpikeCluster,length(PlotInfo.EventNr(1):PlotInfo.EventNr(2)),"Non",app.SpikeRateNumBinsEditField.Value,app.UIAxes_3,app.UIAxes_5,app.Mainapp.Data.Spikes.ChannelPosition,app.Mainapp.Data.Info.NativeSamplingRate,PlotInfo.ChannelsToPlot)
            end
        end

        % Value changed function: BaselineNormalizeCheckBox
        function BaselineNormalizeCheckBoxValueChanged(app, event)
            %% Prepare Plots
            [PlotInfo,SpikeTimes,SpikePositions,SpikeAmplitude,SpikeCluster,~,~,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value] = Event_Spikes_Prepare_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.SpikeRateNumBinsEditField.Value,"Internal");

            if strcmp(app.AnalysisTypeDropDown.Value,"Spike Map")
                 
            elseif strcmp(app.AnalysisTypeDropDown.Value,"Spike Rate Heatmap")
                Event_Spikes_Plot_Heatmap_Spike_Rate(SpikeTimes,SpikePositions,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,PlotInfo.time_bin_size,PlotInfo.depth_edges,PlotInfo.time_edges,length(PlotInfo.EventNr(1):PlotInfo.EventNr(2)),0,app.BaselineNormalizeCheckBox.Value,PlotInfo.NormWindow,"Non",SpikeCluster,app.rgbMatrix,PlotInfo.ChannelsToPlot,app.Mainapp.Data.Info.ChannelSpacing,"Internal")
                Event_Spikes_Plot_Spike_Rate(app.Mainapp.Data,PlotInfo.Time,"BinsizeChangeInitial",app.rgbMatrix,SpikeTimes,SpikePositions,SpikeCluster,length(PlotInfo.EventNr(1):PlotInfo.EventNr(2)),"Non",app.SpikeRateNumBinsEditField.Value,app.UIAxes_3,app.UIAxes_5,app.Mainapp.Data.Spikes.ChannelPosition,app.Mainapp.Data.Info.NativeSamplingRate,PlotInfo.ChannelsToPlot)
            end
        end

        % Value changed function: BaselineWindowStartStopinsEditField
        function BaselineWindowStartStopinsEditFieldValueChanged(app, event)
            %% Prepare Plots
            [PlotInfo,SpikeTimes,SpikePositions,SpikeAmplitude,SpikeCluster,~,~,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value] = Event_Spikes_Prepare_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.SpikeRateNumBinsEditField.Value,"Internal");

            if strcmp(app.AnalysisTypeDropDown.Value,"Spike Map")
                 
            elseif strcmp(app.AnalysisTypeDropDown.Value,"Spike Rate Heatmap")
                Event_Spikes_Plot_Heatmap_Spike_Rate(SpikeTimes,SpikePositions,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,PlotInfo.time_bin_size,PlotInfo.depth_edges,PlotInfo.time_edges,length(PlotInfo.EventNr(1):PlotInfo.EventNr(2)),0,app.BaselineNormalizeCheckBox.Value,PlotInfo.NormWindow,"Non",SpikeCluster,app.rgbMatrix,PlotInfo.ChannelsToPlot,app.Mainapp.Data.Info.ChannelSpacing,"Internal")
                Event_Spikes_Plot_Spike_Rate(app.Mainapp.Data,PlotInfo.Time,"BinsizeChangeInitial",app.rgbMatrix,SpikeTimes,SpikePositions,SpikeCluster,length(PlotInfo.EventNr(1):PlotInfo.EventNr(2)),"Non",app.SpikeRateNumBinsEditField.Value,app.UIAxes_3,app.UIAxes_5,app.Mainapp.Data.Spikes.ChannelPosition,app.Mainapp.Data.Info.NativeSamplingRate,PlotInfo.ChannelsToPlot)
            end
        end

        % Menu selected function: ChangeTimeBinSizeMenu
        function ChangeTimeBinSizeMenuSelected(app, event)
            BinSizeChangeWindow(app,"TimeBins");
        end

        % Value changed function: ChannelSelectionforPlottingEditField
        function ChannelSelectionforPlottingEditFieldValueChanged(app, event)
            %% Prepare Plots
            [PlotInfo,SpikeTimes,SpikePositions,SpikeAmplitude,SpikeCluster,~,~,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value] = Event_Spikes_Prepare_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.SpikeRateNumBinsEditField.Value,"Internal");

            if strcmp(app.AnalysisTypeDropDown.Value,"Spike Map")
                
                Spikes_Plot_Spike_Times("Eventrelated",app.rgbMatrix,PlotInfo.Time,SpikeTimes,SpikePositions,SpikeCluster,SpikeAmplitude,app.Mainapp.Data.Spikes.ChannelPosition,app.UIAxes,[],"Non",[],[],PlotInfo.ChannelsToPlot,app.Mainapp.Data.Info.ChannelSpacing)
                Event_Spikes_Plot_Spike_Rate(app.Mainapp.Data,PlotInfo.Time,"BinsizeChangeInitial",app.rgbMatrix,SpikeTimes,SpikePositions,SpikeCluster,length(PlotInfo.EventNr(1):PlotInfo.EventNr(2)),"Non",app.SpikeRateNumBinsEditField.Value,app.UIAxes_3,app.UIAxes_5,app.Mainapp.Data.Spikes.ChannelPosition,app.Mainapp.Data.Info.NativeSamplingRate,PlotInfo.ChannelsToPlot)

            elseif strcmp(app.AnalysisTypeDropDown.Value,"Spike Rate Heatmap")
                Event_Spikes_Plot_Heatmap_Spike_Rate(SpikeTimes,SpikePositions,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,PlotInfo.time_bin_size,PlotInfo.depth_edges,PlotInfo.time_edges,length(PlotInfo.EventNr(1):PlotInfo.EventNr(2)),0,app.BaselineNormalizeCheckBox.Value,PlotInfo.NormWindow,"Non",SpikeCluster,app.rgbMatrix,PlotInfo.ChannelsToPlot,app.Mainapp.Data.Info.ChannelSpacing,"Internal")
                Event_Spikes_Plot_Spike_Rate(app.Mainapp.Data,PlotInfo.Time,"BinsizeChangeInitial",app.rgbMatrix,SpikeTimes,SpikePositions,SpikeCluster,length(PlotInfo.EventNr(1):PlotInfo.EventNr(2)),"Non",app.SpikeRateNumBinsEditField.Value,app.UIAxes_3,app.UIAxes_5,app.Mainapp.Data.Spikes.ChannelPosition,app.Mainapp.Data.Info.NativeSamplingRate,PlotInfo.ChannelsToPlot)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Event_Related_InternalSpikeAnalysisUIFigure = uifigure('Visible', 'off');
            app.Event_Related_InternalSpikeAnalysisUIFigure.Name = 'Event_Related_Internal Spike Analysis';
            app.Event_Related_InternalSpikeAnalysisUIFigure.Icon = 'Logo.png';
            app.SnapshotMenu = uimenu(app.Event_Related_InternalSpikeAnalysisUIFigure);
            app.SnapshotMenu.Text = '   Snapshot   ';
            app.SaveSnapshotofMainWindowMenu = uimenu(app.SnapshotMenu);
            app.SaveSnapshotofMainWindowMenu.Text = 'Save Snapshot of Main Window';
            app.PNGMenu = uimenu(app.SaveSnapshotofMainWindowMenu);
            app.PNGMenu.MenuSelectedFcn = createCallbackFcn(app, @PNGMenuSelected, true);
            app.PNGMenu.Text = 'PNG';
            app.JPGMenu = uimenu(app.SaveSnapshotofMainWindowMenu);
            app.JPGMenu.MenuSelectedFcn = createCallbackFcn(app, @JPGMenuSelected, true);
            app.JPGMenu.Text = 'JPG';
            app.UIAxes_3 = uiaxes(app.Event_Related_InternalSpikeAnalysisUIFigure);
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes = uiaxes(app.Event_Related_InternalSpikeAnalysisUIFigure);
            title(app.UIAxes, 'Title')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes_5 = uiaxes(app.Event_Related_InternalSpikeAnalysisUIFigure);
            xlabel(app.UIAxes_5, 'X')
            zlabel(app.UIAxes_5, 'Z')
            app.Panel = uipanel(app.Event_Related_InternalSpikeAnalysisUIFigure);
            app.SpikeRateNumBinsEditFieldLabel = uilabel(app.Panel);
            app.SpikeRateNumBinsEditFieldLabel.Text = {'Spike Rate'; 'Num Bins'};
            app.SpikeRateNumBinsEditField = uieditfield(app.Panel, 'text');
            app.SpikeRateNumBinsEditField.ValueChangedFcn = createCallbackFcn(app, @SpikeRateNumBinsEditFieldValueChanged, true);
            app.SpikeRateNumBinsEditField.Value = '100';
            app.EventRangeEditFieldLabel = uilabel(app.Panel);
            app.EventRangeEditFieldLabel.Text = 'Event Range';
            app.EventRangeEditField = uieditfield(app.Panel, 'text');
            app.EventRangeEditField.ValueChangedFcn = createCallbackFcn(app, @EventRangeEditFieldValueChanged, true);
            app.AnalysisTypeDropDownLabel = uilabel(app.Panel);
            app.AnalysisTypeDropDownLabel.Text = 'Analysis Type';
            app.AnalysisTypeDropDown = uidropdown(app.Panel);
            app.AnalysisTypeDropDown.Items = {'Spike Map', 'Spike Rate Heatmap'};
            app.AnalysisTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @AnalysisTypeDropDownValueChanged, true);
            app.AnalysisTypeDropDown.Value = 'Spike Map';
            app.BaselineNormalizeCheckBox = uicheckbox(app.Panel);
            app.BaselineNormalizeCheckBox.ValueChangedFcn = createCallbackFcn(app, @BaselineNormalizeCheckBoxValueChanged, true);
            app.BaselineNormalizeCheckBox.Text = '  Baseline Normalize';
            app.BaselineWindowStartStopinsEditFieldLabel = uilabel(app.Panel);
            app.BaselineWindowStartStopinsEditFieldLabel.Text = {'Baseline Window'; '[Start,Stop] in s'};
            app.BaselineWindowStartStopinsEditField = uieditfield(app.Panel, 'text');
            app.BaselineWindowStartStopinsEditField.ValueChangedFcn = createCallbackFcn(app, @BaselineWindowStartStopinsEditFieldValueChanged, true);
            app.TextArea = uitextarea(app.Panel);
            app.ChannelSelectionforPlottingEditFieldLabel = uilabel(app.Panel);
            app.ChannelSelectionforPlottingEditFieldLabel.Text = {'Channel Selection'; 'for Plotting'};
            app.ChannelSelectionforPlottingEditField = uieditfield(app.Panel, 'text');
            app.ChannelSelectionforPlottingEditField.ValueChangedFcn = createCallbackFcn(app, @ChannelSelectionforPlottingEditFieldValueChanged, true);
            app.EventRelatedInternalSpikesWindowLabel = uilabel(app.Event_Related_InternalSpikeAnalysisUIFigure);
            app.EventRelatedInternalSpikesWindowLabel.Text = 'Event Related Internal Spikes Window';
            app.Image = uiimage(app.Event_Related_InternalSpikeAnalysisUIFigure);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodToolboxLabel = uilabel(app.Event_Related_InternalSpikeAnalysisUIFigure);
            app.NeuromodToolboxLabel.Text = 'Neuromod Toolbox';
            app.CloseButton = uibutton(app.Event_Related_InternalSpikeAnalysisUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.ContextMenu3 = uicontextmenu(app.Event_Related_InternalSpikeAnalysisUIFigure);
            app.ChangeTimeBinSizeMenu = uimenu(app.ContextMenu3);
            app.ChangeTimeBinSizeMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeTimeBinSizeMenuSelected, true);
            app.ChangeTimeBinSizeMenu.Text = 'Change Time Bin Size';
            app.ChangeTitleMenu = uimenu(app.ContextMenu3);
            app.ChangeTitleMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeTitleMenuSelected, true);
            app.ChangeTitleMenu.Text = 'Change Title';
            app.ChangeXLabelMenu = uimenu(app.ContextMenu3);
            app.ChangeXLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeXLabelMenuSelected, true);
            app.ChangeXLabelMenu.Text = 'Change X Label';
            app.ChangeYLabelMenu = uimenu(app.ContextMenu3);
            app.ChangeYLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeYLabelMenuSelected, true);
            app.ChangeYLabelMenu.Text = 'Change Y Label';
            app.ChangeXlimMenu = uimenu(app.ContextMenu3);
            app.ChangeXlimMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeXlimMenuSelected, true);
            app.ChangeXlimMenu.Text = 'Change Xlim';
            app.ChangeYlimMenu = uimenu(app.ContextMenu3);
            app.ChangeYlimMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeYlimMenuSelected, true);
            app.ChangeYlimMenu.Text = 'Change Ylim';
            app.ChangeLegendMenu = uimenu(app.ContextMenu3);
            app.ChangeLegendMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeLegendMenuSelected, true);
            app.ChangeLegendMenu.Text = 'Change Legend';
            app.ChangeFontsizeMenu = uimenu(app.ContextMenu3);
            app.ChangeFontsizeMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeFontsizeMenuSelected, true);
            app.ChangeFontsizeMenu.Text = 'Change Fontsize';
            % Assign app.ContextMenu3
            app.UIAxes.ContextMenu = app.ContextMenu3;
            app.ContextMenu3_2 = uicontextmenu(app.Event_Related_InternalSpikeAnalysisUIFigure);
            app.ChangeTitleMenu_2 = uimenu(app.ContextMenu3_2);
            app.ChangeTitleMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ChangeTitleMenu_2Selected, true);
            app.ChangeTitleMenu_2.Text = 'Change Title';
            app.ChangeXLabelMenu_2 = uimenu(app.ContextMenu3_2);
            app.ChangeXLabelMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ChangeXLabelMenu_2Selected, true);
            app.ChangeXLabelMenu_2.Text = 'Change X Label';
            app.ChangeYLabelMenu_2 = uimenu(app.ContextMenu3_2);
            app.ChangeYLabelMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ChangeYLabelMenu_2Selected, true);
            app.ChangeYLabelMenu_2.Text = 'Change Y Label';
            app.ChangeXlimMenu_2 = uimenu(app.ContextMenu3_2);
            app.ChangeXlimMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ChangeXlimMenu_2Selected, true);
            app.ChangeXlimMenu_2.Text = 'Change Xlim';
            app.ChangeYlimMenu_2 = uimenu(app.ContextMenu3_2);
            app.ChangeYlimMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ChangeYlimMenu_2Selected, true);
            app.ChangeYlimMenu_2.Text = 'Change Ylim';
            app.ChangeLegendMenu_2 = uimenu(app.ContextMenu3_2);
            app.ChangeLegendMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ChangeLegendMenu_2Selected, true);
            app.ChangeLegendMenu_2.Text = 'Change Legend';
            app.ChangeFontsizeMenu_2 = uimenu(app.ContextMenu3_2);
            app.ChangeFontsizeMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ChangeFontsizeMenu_2Selected, true);
            app.ChangeFontsizeMenu_2.Text = 'Change Fontsize';
            % Assign app.ContextMenu3_2
            app.UIAxes_3.ContextMenu = app.ContextMenu3_2;
            app.ContextMenu3_3 = uicontextmenu(app.Event_Related_InternalSpikeAnalysisUIFigure);
            app.ChangeTitleMenu_3 = uimenu(app.ContextMenu3_3);
            app.ChangeTitleMenu_3.MenuSelectedFcn = createCallbackFcn(app, @ChangeTitleMenu_3Selected, true);
            app.ChangeTitleMenu_3.Text = 'Change Title';
            app.ChangeXLabelMenu_3 = uimenu(app.ContextMenu3_3);
            app.ChangeXLabelMenu_3.MenuSelectedFcn = createCallbackFcn(app, @ChangeXLabelMenu_3Selected, true);
            app.ChangeXLabelMenu_3.Text = 'Change X Label';
            app.ChangeYLabelMenu_3 = uimenu(app.ContextMenu3_3);
            app.ChangeYLabelMenu_3.MenuSelectedFcn = createCallbackFcn(app, @ChangeYLabelMenu_3Selected, true);
            app.ChangeYLabelMenu_3.Text = 'Change Y Label';
            app.ChangeXlimMenu_3 = uimenu(app.ContextMenu3_3);
            app.ChangeXlimMenu_3.MenuSelectedFcn = createCallbackFcn(app, @ChangeXlimMenu_3Selected, true);
            app.ChangeXlimMenu_3.Text = 'Change Xlim';
            app.ChangeYlimMenu_3 = uimenu(app.ContextMenu3_3);
            app.ChangeYlimMenu_3.MenuSelectedFcn = createCallbackFcn(app, @ChangeYlimMenu_3Selected, true);
            app.ChangeYlimMenu_3.Text = 'Change Ylim';
            app.ChangeLegendMenu_3 = uimenu(app.ContextMenu3_3);
            app.ChangeLegendMenu_3.MenuSelectedFcn = createCallbackFcn(app, @ChangeLegendMenu_3Selected, true);
            app.ChangeLegendMenu_3.Text = 'Change Legend';
            app.ChangeFontsizeMenu_3 = uimenu(app.ContextMenu3_3);
            app.ChangeFontsizeMenu_3.MenuSelectedFcn = createCallbackFcn(app, @ChangeFontsizeMenu_3Selected, true);
            app.ChangeFontsizeMenu_3.Text = 'Change Fontsize';
            % Assign app.ContextMenu3_3
            app.UIAxes_5.ContextMenu = app.ContextMenu3_3;
            % Show the figure after all components are created
            app.Event_Related_InternalSpikeAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Events_Internal_Spike_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.Event_Related_InternalSpikeAnalysisUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.Event_Related_InternalSpikeAnalysisUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Event_Related_InternalSpikeAnalysisUIFigure)
        end
    end
end