classdef Events_Kilosort_Spike_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EventRelatedKilosortSpikeAnalysisUIFigure  matlab.ui.Figure
        SnapshotMenu                    matlab.ui.container.Menu
        SaveSnapshotofMainWindowMenu    matlab.ui.container.Menu
        PNGMenu                         matlab.ui.container.Menu
        JPGMenu                         matlab.ui.container.Menu
        CloseButton                     matlab.ui.control.Button
        NeuromodToolboxLabel            matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        KilosortEventRelatedAnalysisWindowLabel  matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        ChannelSelectionforPlottingEditField  matlab.ui.control.EditField
        ChannelSelectionforPlottingEditFieldLabel  matlab.ui.control.Label
        AnalysisTypeDropDown            matlab.ui.control.DropDown
        AnalysisTypeDropDownLabel       matlab.ui.control.Label
        BaselineNormalizeCheckBox       matlab.ui.control.CheckBox
        BaselineWindowStartStopinsEditField  matlab.ui.control.EditField
        BaselineWindowStartStopinsEditFieldLabel  matlab.ui.control.Label
        EventRangeEditField             matlab.ui.control.EditField
        EventRangeEditFieldLabel        matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        ClustertoshowDropDown           matlab.ui.control.DropDown
        ClustertoShowDropDownLabel      matlab.ui.control.Label
        SpikeRateNumBinsEditField       matlab.ui.control.EditField
        SpikeRateNumBinsEditFieldLabel  matlab.ui.control.Label
        UIAxes_5                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        ContextMenu                     matlab.ui.container.ContextMenu
        ChangeTimeBinSizeMenu           matlab.ui.container.Menu
        ChangeDepthBinSizeMenu          matlab.ui.container.Menu
    end

    
    properties (Access = public)
        Mainapp = []; % Description
        numCluster = [];
        rgbMatrix = [];
        SpikeBinSettings = [];
        Settings = [];
        SpikeData = [];
        EventRange = [];
        TimearoundEvent = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            app.Mainapp = Mainapp;

            movegui(app.EventRelatedKilosortSpikeAnalysisUIFigure, 'center');
            
            [app] = Spikes_Set_Up_Spike_Analysis_Windows(app,[],"EventWindow");

            %% Prepare Plots
            [PlotInfo,SpikeTimes,SpikePositions,SpikeAmplitude,SpikeCluster,~,~,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value] = Event_Spikes_Prepare_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.SpikeRateNumBinsEditField.Value,"Kilosort");

            if strcmp(app.AnalysisTypeDropDown.Value,"Spike Map")
                Spikes_Plot_Spike_Times("Eventrelated",app.rgbMatrix,PlotInfo.Time,SpikeTimes,SpikePositions,SpikeCluster,SpikeAmplitude,app.Mainapp.Data.Spikes.ChannelPosition,app.UIAxes,app.numCluster,app.ClustertoshowDropDown.Value,[],[],PlotInfo.ChannelsToPlot,app.Mainapp.Data.Info.ChannelSpacing)
                Event_Spikes_Plot_Spike_Rate(app.Mainapp.Data,PlotInfo.Time,"Initial",app.rgbMatrix,SpikeTimes,SpikePositions,SpikeCluster,length(PlotInfo.EventNr(1):PlotInfo.EventNr(2)),app.ClustertoshowDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.UIAxes_3,app.UIAxes_5,app.Mainapp.Data.Spikes.ChannelPosition,app.Mainapp.Data.Info.NativeSamplingRate,PlotInfo.ChannelsToPlot)
            elseif strcmp(app.AnalysisTypeDropDown.Value,"Spike Rate Heatmap")
                Event_Spikes_Plot_Spike_Rate(app.Mainapp.Data,PlotInfo.Time,"Initial",app.rgbMatrix,SpikeTimes,SpikePositions,SpikeCluster,length(PlotInfo.EventNr(1):PlotInfo.EventNr(2)),app.ClustertoshowDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.UIAxes_3,app.UIAxes_5,app.Mainapp.Data.Spikes.ChannelPosition,app.Mainapp.Data.Info.NativeSamplingRate,PlotInfo.ChannelsToPlot)
            end
        end

        % Value changed function: ClustertoshowDropDown
        function ClustertoshowDropDownValueChanged(app, event)
            app.UIAxes.NextPlot = "replace"; 
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add"; 
            set(app.UIAxes, 'YDir','reverse');
            set(app.UIAxes,'xticklabel',{[]});

            %% Prepare Plots
            [PlotInfo,SpikeTimes,SpikePositions,SpikeAmplitude,SpikeCluster,~,~,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value] = Event_Spikes_Prepare_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.SpikeRateNumBinsEditField.Value,"Kilosort");
            if strcmp(app.AnalysisTypeDropDown.Value,"Spike Map")
                Spikes_Plot_Spike_Times("Eventrelated",app.rgbMatrix,PlotInfo.Time,SpikeTimes,SpikePositions,SpikeCluster,SpikeAmplitude,app.Mainapp.Data.Spikes.ChannelPosition,app.UIAxes,app.numCluster,app.ClustertoshowDropDown.Value,[],[],PlotInfo.ChannelsToPlot,app.Mainapp.Data.Info.ChannelSpacing)
                if ~strcmp(app.ClustertoshowDropDown.Value,'Non') && ~strcmp(app.ClustertoshowDropDown.Value,'All')
                    Event_Spikes_Plot_Spike_Rate(app.Mainapp.Data,PlotInfo.Time,"NewCluster",app.rgbMatrix,SpikeTimes,SpikePositions,SpikeCluster,length(PlotInfo.EventNr(1):PlotInfo.EventNr(2)),app.ClustertoshowDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.UIAxes_3,app.UIAxes_5,app.Mainapp.Data.Spikes.ChannelPosition,app.Mainapp.Data.Info.NativeSamplingRate,PlotInfo.ChannelsToPlot)
                end
                app.BaselineNormalizeCheckBox.Enable = "off";
                app.BaselineWindowStartStopinsEditField.Enable = "off";
            elseif strcmp(app.AnalysisTypeDropDown.Value,"Spike Rate Heatmap")
                app.BaselineNormalizeCheckBox.Enable = "on";
                app.BaselineWindowStartStopinsEditField.Enable = "on";
                Event_Spikes_Plot_Heatmap_Spike_Rate(SpikeTimes,SpikePositions,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,PlotInfo.time_bin_size,PlotInfo.depth_edges,PlotInfo.time_edges,length(PlotInfo.EventNr(1):PlotInfo.EventNr(2)),0,app.BaselineNormalizeCheckBox.Value,PlotInfo.NormWindow,app.ClustertoshowDropDown.Value,SpikeCluster,app.rgbMatrix,PlotInfo.ChannelsToPlot,app.Mainapp.Data.Info.ChannelSpacing,"Kilosort")
                if ~strcmp(app.ClustertoshowDropDown.Value,'Non') && ~strcmp(app.ClustertoshowDropDown.Value,'All')
                    %Spikes_Plot_Spike_Times("Eventrelated",app.rgbMatrix,PlotInfo.Time,SpikeTimes,SpikePositions,SpikeCluster,SpikeAmplitude,app.Mainapp.Data.Spikes.ChannelPosition,app.UIAxes,app.numCluster,app.ClustertoshowDropDown.Value,[],[],PlotInfo.ChannelsToPlot,app.Mainapp.Data.Info.ChannelSpacing)
                    Event_Spikes_Plot_Spike_Rate(app.Mainapp.Data,PlotInfo.Time,"NewCluster",app.rgbMatrix,SpikeTimes,SpikePositions,SpikeCluster,length(PlotInfo.EventNr(1):PlotInfo.EventNr(2)),app.ClustertoshowDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.UIAxes_3,app.UIAxes_5,app.Mainapp.Data.Spikes.ChannelPosition,app.Mainapp.Data.Info.NativeSamplingRate,PlotInfo.ChannelsToPlot)
                end
            % elseif strcmp(app.AnalysisTypeDropDown.Value,"Spike Triggered LFP")
            %     if ~isfield(app.Mainapp.Data.Info,'DownsampleFactor')
            %         SpikeTimes = app.GeneralSettings.SpikeTimes/app.Mainapp.Data.Info.NativeSamplingRate;
            %         Time = app.GeneralSettings.Time+app.TimearoundEvent(1);
            %         mnLFP = spikeTrigLFP(Time, app.Mainapp.Data.Preprocessed, SpikeTimes', [], app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate);
            %     else
            %         SpikeTimes = app.GeneralSettings.SpikeTimes/app.Mainapp.Data.Info.NativeSamplingRate;
            %         Time = -app.TimearoundEvent(1):1/app.Mainapp.Data.Info.DownsampledSampleRate:app.TimearoundEvent(2);
            %         Time = Time+app.TimearoundEvent(1);
            %         for nchannel = 1:size(app.Mainapp.Data.EventRelatedData,1)
            %             for nevents = 1:size(app.Mainapp.Data.EventRelatedData,2)
            %                 mnLFP = spikeTrigLFP(Time, app.Mainapp.Data.Preprocessed, SpikeTimes', [], app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate);
            %             end
            %         end
            %     end
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: SpikeRateNumBinsEditField
        function SpikeRateNumBinsEditFieldValueChanged(app, event)
            % Reset Spike Rate plot in order to show new bin size
            yyaxis(app.UIAxes_3, 'left');
            app.UIAxes_3.NextPlot = "replace"; 
            plot(app.UIAxes_3,0,0);
            app.UIAxes_3.NextPlot = "add";
            set(app.UIAxes_3, 'YDir','reverse');
            app.UIAxes_3.FontSize = 10;
            ylabel(app.UIAxes_3,"Spike Rate [Hz]")

            yyaxis(app.UIAxes_3, 'right');
            app.UIAxes_3.NextPlot = "replace"; 
            plot(app.UIAxes_3,0,0);
            app.UIAxes_3.NextPlot = "add";
            set(app.UIAxes_3, 'YDir','reverse');
            ylabel(app.UIAxes_3,"Spike Rate [Hz]")
            app.UIAxes_3.FontSize = 10;

           %% Prepare Plots
            [PlotInfo,SpikeTimes,SpikePositions,SpikeAmplitude,SpikeCluster,~,~,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value] = Event_Spikes_Prepare_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.SpikeRateNumBinsEditField.Value,"Kilosort");
            if strcmp(app.AnalysisTypeDropDown.Value,"Spike Map")
                Event_Spikes_Plot_Spike_Rate(app.Mainapp.Data,PlotInfo.Time,"BinsizeChangeInitial",app.rgbMatrix,SpikeTimes,SpikePositions,SpikeCluster,length(PlotInfo.EventNr(1):PlotInfo.EventNr(2)),app.ClustertoshowDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.UIAxes_3,app.UIAxes_5,app.Mainapp.Data.Spikes.ChannelPosition,app.Mainapp.Data.Info.NativeSamplingRate,PlotInfo.ChannelsToPlot)
                if ~strcmp(app.ClustertoshowDropDown.Value,'Non') && ~strcmp(app.ClustertoshowDropDown.Value,'All')
                    Event_Spikes_Plot_Spike_Rate(app.Mainapp.Data,PlotInfo.Time,"NewCluster",app.rgbMatrix,SpikeTimes,SpikePositions,SpikeCluster,length(PlotInfo.EventNr(1):PlotInfo.EventNr(2)),app.ClustertoshowDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.UIAxes_3,app.UIAxes_5,app.Mainapp.Data.Spikes.ChannelPosition,app.Mainapp.Data.Info.NativeSamplingRate,PlotInfo.ChannelsToPlot)
                end
            elseif strcmp(app.AnalysisTypeDropDown.Value,"Spike Rate Heatmap")
                Event_Spikes_Plot_Heatmap_Spike_Rate(SpikeTimes,SpikePositions,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,PlotInfo.time_bin_size,PlotInfo.depth_edges,PlotInfo.time_edges,length(PlotInfo.EventNr(1):PlotInfo.EventNr(2)),0,app.BaselineNormalizeCheckBox.Value,PlotInfo.NormWindow,app.ClustertoshowDropDown.Value,SpikeCluster,app.rgbMatrix,PlotInfo.ChannelsToPlot,app.Mainapp.Data.Info.ChannelSpacing,"Kilosort")
                Event_Spikes_Plot_Spike_Rate(app.Mainapp.Data,PlotInfo.Time,"BinsizeChangeInitial",app.rgbMatrix,SpikeTimes,SpikePositions,SpikeCluster,length(PlotInfo.EventNr(1):PlotInfo.EventNr(2)),app.ClustertoshowDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.UIAxes_3,app.UIAxes_5,app.Mainapp.Data.Spikes.ChannelPosition,app.Mainapp.Data.Info.NativeSamplingRate,PlotInfo.ChannelsToPlot)
                if ~strcmp(app.ClustertoshowDropDown.Value,'Non') && ~strcmp(app.ClustertoshowDropDown.Value,'All')
                    Event_Spikes_Plot_Spike_Rate(app.Mainapp.Data,PlotInfo.Time,"NewCluster",app.rgbMatrix,SpikeTimes,SpikePositions,SpikeCluster,length(PlotInfo.EventNr(1):PlotInfo.EventNr(2)),app.ClustertoshowDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.UIAxes_3,app.UIAxes_5,app.Mainapp.Data.Spikes.ChannelPosition,app.Mainapp.Data.Info.NativeSamplingRate,PlotInfo.ChannelsToPlot)
                end
            end
        end

        % Menu selected function: PNGMenu
        function PNGMenuSelected(app, event)
            [file, path] = uiputfile('*.png', 'Save as');

            exportapp(app.EventRelatedKilosortSpikeAnalysisUIFigure,fullfile(path,file)) 
        end

        % Menu selected function: JPGMenu
        function JPGMenuSelected(app, event)
            [file, path] = uiputfile('*.jpg', 'Save as');

            exportapp(app.EventRelatedKilosortSpikeAnalysisUIFigure,fullfile(path,file)) 
        end

        % Value changed function: EventRangeEditField
        function EventRangeEditFieldValueChanged(app, event)
            % Reset Spike Rate plot in order to show new bin size
            yyaxis(app.UIAxes_3, 'left');
            app.UIAxes_3.NextPlot = "replace"; 
            plot(app.UIAxes_3,0,0);
            app.UIAxes_3.NextPlot = "add";
            set(app.UIAxes_3, 'YDir','reverse');
            app.UIAxes_3.FontSize = 10;
            ylabel(app.UIAxes_3,"Spike Rate [Hz]")

            yyaxis(app.UIAxes_3, 'right');
            app.UIAxes_3.NextPlot = "replace"; 
            plot(app.UIAxes_3,0,0);
            app.UIAxes_3.NextPlot = "add";
            set(app.UIAxes_3, 'YDir','reverse');
            ylabel(app.UIAxes_3,"Spike Rate [Hz]")
            app.UIAxes_3.FontSize = 10;

            %% Prepare Plots
            [PlotInfo,SpikeTimes,SpikePositions,SpikeAmplitude,SpikeCluster,~,~,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value] = Event_Spikes_Prepare_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.SpikeRateNumBinsEditField.Value,"Kilosort");

            if strcmp(app.AnalysisTypeDropDown.Value,"Spike Map")
                if ~strcmp(app.ClustertoshowDropDown.Value,"All") && ~strcmp(app.ClustertoshowDropDown.Value,"Non")
                    Spikes_Plot_Spike_Times("Eventrelated",app.rgbMatrix,PlotInfo.Time,SpikeTimes,SpikePositions,SpikeCluster,SpikeAmplitude,app.Mainapp.Data.Spikes.ChannelPosition,app.UIAxes,app.numCluster,"Non",[],[],PlotInfo.ChannelsToPlot,app.Mainapp.Data.Info.ChannelSpacing)
                end
                Spikes_Plot_Spike_Times("Eventrelated",app.rgbMatrix,PlotInfo.Time,SpikeTimes,SpikePositions,SpikeCluster,SpikeAmplitude,app.Mainapp.Data.Spikes.ChannelPosition,app.UIAxes,app.numCluster,app.ClustertoshowDropDown.Value,[],[],PlotInfo.ChannelsToPlot,app.Mainapp.Data.Info.ChannelSpacing)
                Event_Spikes_Plot_Spike_Rate(app.Mainapp.Data,PlotInfo.Time,"BinsizeChangeInitial",app.rgbMatrix,SpikeTimes,SpikePositions,SpikeCluster,length(PlotInfo.EventNr(1):PlotInfo.EventNr(2)),app.ClustertoshowDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.UIAxes_3,app.UIAxes_5,app.Mainapp.Data.Spikes.ChannelPosition,app.Mainapp.Data.Info.NativeSamplingRate,PlotInfo.ChannelsToPlot)
                if ~strcmp(app.ClustertoshowDropDown.Value,'Non') && ~strcmp(app.ClustertoshowDropDown.Value,'All')
                    Event_Spikes_Plot_Spike_Rate(app.Mainapp.Data,PlotInfo.Time,"NewCluster",app.rgbMatrix,SpikeTimes,SpikePositions,SpikeCluster,length(PlotInfo.EventNr(1):PlotInfo.EventNr(2)),app.ClustertoshowDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.UIAxes_3,app.UIAxes_5,app.Mainapp.Data.Spikes.ChannelPosition,app.Mainapp.Data.Info.NativeSamplingRate,PlotInfo.ChannelsToPlot)
                end
            elseif strcmp(app.AnalysisTypeDropDown.Value,"Spike Rate Heatmap")
                Event_Spikes_Plot_Heatmap_Spike_Rate(SpikeTimes,SpikePositions,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,PlotInfo.time_bin_size,PlotInfo.depth_edges,PlotInfo.time_edges,length(PlotInfo.EventNr(1):PlotInfo.EventNr(2)),0,app.BaselineNormalizeCheckBox.Value,PlotInfo.NormWindow,app.ClustertoshowDropDown.Value,SpikeCluster,app.rgbMatrix,PlotInfo.ChannelsToPlot,app.Mainapp.Data.Info.ChannelSpacing,"Kilosort")
                Event_Spikes_Plot_Spike_Rate(app.Mainapp.Data,PlotInfo.Time,"BinsizeChangeInitial",app.rgbMatrix,SpikeTimes,SpikePositions,SpikeCluster,length(PlotInfo.EventNr(1):PlotInfo.EventNr(2)),app.ClustertoshowDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.UIAxes_3,app.UIAxes_5,app.Mainapp.Data.Spikes.ChannelPosition,app.Mainapp.Data.Info.NativeSamplingRate,PlotInfo.ChannelsToPlot)
                if ~strcmp(app.ClustertoshowDropDown.Value,'Non') && ~strcmp(app.ClustertoshowDropDown.Value,'All')
                    Event_Spikes_Plot_Spike_Rate(app.Mainapp.Data,PlotInfo.Time,"NewCluster",app.rgbMatrix,SpikeTimes,SpikePositions,SpikeCluster,length(PlotInfo.EventNr(1):PlotInfo.EventNr(2)),app.ClustertoshowDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.UIAxes_3,app.UIAxes_5,app.Mainapp.Data.Spikes.ChannelPosition,app.Mainapp.Data.Info.NativeSamplingRate,PlotInfo.ChannelsToPlot)
                end
            end  
        end

        % Value changed function: AnalysisTypeDropDown
        function AnalysisTypeDropDownValueChanged(app, event)
            app.UIAxes.NextPlot = "replace"; 
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add"; 
            set(app.UIAxes, 'YDir','reverse');
            set(app.UIAxes,'xticklabel',{[]});

            %% Prepare Plots
            [PlotInfo,SpikeTimes,SpikePositions,SpikeAmplitude,SpikeCluster,~,~,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value] = Event_Spikes_Prepare_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.SpikeRateNumBinsEditField.Value,"Kilosort");

            if strcmp(app.AnalysisTypeDropDown.Value,"Spike Map")
                Spikes_Plot_Spike_Times("Eventrelated",app.rgbMatrix,PlotInfo.Time,SpikeTimes,SpikePositions,SpikeCluster,SpikeAmplitude,app.Mainapp.Data.Spikes.ChannelPosition,app.UIAxes,app.numCluster,app.ClustertoshowDropDown.Value,[],[],PlotInfo.ChannelsToPlot,app.Mainapp.Data.Info.ChannelSpacing)
                if ~strcmp(app.ClustertoshowDropDown.Value,'Non') && ~strcmp(app.ClustertoshowDropDown.Value,'All')
                    Event_Spikes_Plot_Spike_Rate(app.Mainapp.Data,PlotInfo.Time,"NewCluster",app.rgbMatrix,SpikeTimes,SpikePositions,SpikeCluster,length(PlotInfo.EventNr(1):PlotInfo.EventNr(2)),app.ClustertoshowDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.UIAxes_3,app.UIAxes_5,app.Mainapp.Data.Spikes.ChannelPosition,app.Mainapp.Data.Info.NativeSamplingRate,PlotInfo.ChannelsToPlot)
                end
                app.BaselineNormalizeCheckBox.Enable = "off";
                app.BaselineWindowStartStopinsEditField.Enable = "off";
            elseif strcmp(app.AnalysisTypeDropDown.Value,"Spike Rate Heatmap")
                app.BaselineNormalizeCheckBox.Enable = "on";
                app.BaselineWindowStartStopinsEditField.Enable = "on";
                Event_Spikes_Plot_Heatmap_Spike_Rate(SpikeTimes,SpikePositions,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,PlotInfo.time_bin_size,PlotInfo.depth_edges,PlotInfo.time_edges,length(PlotInfo.EventNr(1):PlotInfo.EventNr(2)),0,app.BaselineNormalizeCheckBox.Value,PlotInfo.NormWindow,app.ClustertoshowDropDown.Value,SpikeCluster,app.rgbMatrix,PlotInfo.ChannelsToPlot,app.Mainapp.Data.Info.ChannelSpacing,"Kilosort")
                if ~strcmp(app.ClustertoshowDropDown.Value,'Non') && ~strcmp(app.ClustertoshowDropDown.Value,'All')
                    Event_Spikes_Plot_Spike_Rate(app.Mainapp.Data,PlotInfo.Time,"NewCluster",app.rgbMatrix,SpikeTimes,SpikePositions,SpikeCluster,length(PlotInfo.EventNr(1):PlotInfo.EventNr(2)),app.ClustertoshowDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.UIAxes_3,app.UIAxes_5,app.Mainapp.Data.Spikes.ChannelPosition,app.Mainapp.Data.Info.NativeSamplingRate,PlotInfo.ChannelsToPlot)
                end
            % elseif strcmp(app.AnalysisTypeDropDown.Value,"Spike Triggered LFP")
            %     if ~isfield(app.Mainapp.Data.Info,'DownsampleFactor')
            %         SpikeTimes = app.GeneralSettings.SpikeTimes/app.Mainapp.Data.Info.NativeSamplingRate;
            %         Time = app.GeneralSettings.Time+app.TimearoundEvent(1);
            %         mnLFP = spikeTrigLFP(Time, app.Mainapp.Data.Preprocessed, SpikeTimes', [], app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate);
            %     else
            %         SpikeTimes = app.GeneralSettings.SpikeTimes/app.Mainapp.Data.Info.NativeSamplingRate;
            %         Time = -app.TimearoundEvent(1):1/app.Mainapp.Data.Info.DownsampledSampleRate:app.TimearoundEvent(2);
            %         Time = Time+app.TimearoundEvent(1);
            %         for nchannel = 1:size(app.Mainapp.Data.EventRelatedData,1)
            %             for nevents = 1:size(app.Mainapp.Data.EventRelatedData,2)
            %                 mnLFP = spikeTrigLFP(Time, app.Mainapp.Data.Preprocessed, SpikeTimes', [], app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate);
            %             end
            %         end
            %     end
            end  
        end

        % Value changed function: BaselineNormalizeCheckBox
        function BaselineNormalizeCheckBoxValueChanged(app, event)
            %% Prepare Plots
            [PlotInfo,SpikeTimes,SpikePositions,SpikeAmplitude,SpikeCluster,~,~,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value] = Event_Spikes_Prepare_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.SpikeRateNumBinsEditField.Value,"Kilosort");

            if strcmp(app.AnalysisTypeDropDown.Value,"Spike Map")
            elseif strcmp(app.AnalysisTypeDropDown.Value,"Spike Rate Heatmap")
                Event_Spikes_Plot_Heatmap_Spike_Rate(SpikeTimes,SpikePositions,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,PlotInfo.time_bin_size,PlotInfo.depth_edges,PlotInfo.time_edges,length(PlotInfo.EventNr(1):PlotInfo.EventNr(2)),0,app.BaselineNormalizeCheckBox.Value,PlotInfo.NormWindow,app.ClustertoshowDropDown.Value,SpikeCluster,app.rgbMatrix,PlotInfo.ChannelsToPlot,app.Mainapp.Data.Info.ChannelSpacing,"Kilosort")
            end  
        end

        % Value changed function: BaselineWindowStartStopinsEditField
        function BaselineWindowStartStopinsEditFieldValueChanged(app, event)
            %% Prepare Plots
            [PlotInfo,SpikeTimes,SpikePositions,SpikeAmplitude,SpikeCluster,~,~,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value] = Event_Spikes_Prepare_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.SpikeRateNumBinsEditField.Value,"Kilosort");

            if strcmp(app.AnalysisTypeDropDown.Value,"Spike Map")
               
            elseif strcmp(app.AnalysisTypeDropDown.Value,"Spike Rate Heatmap")
                Event_Spikes_Plot_Heatmap_Spike_Rate(SpikeTimes,SpikePositions,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,PlotInfo.time_bin_size,PlotInfo.depth_edges,PlotInfo.time_edges,length(PlotInfo.EventNr(1):PlotInfo.EventNr(2)),0,app.BaselineNormalizeCheckBox.Value,PlotInfo.NormWindow,app.ClustertoshowDropDown.Value,SpikeCluster,app.rgbMatrix,PlotInfo.ChannelsToPlot,app.Mainapp.Data.Info.ChannelSpacing,"Kilosort")
            end  
        end

        % Menu selected function: ChangeTimeBinSizeMenu
        function ChangeTimeBinSizeMenuSelected(app, event)
            BinSizeChangeWindow(app,"TimeBins");
        end

        % Menu selected function: ChangeDepthBinSizeMenu
        function ChangeDepthBinSizeMenuSelected(app, event)
            BinSizeChangeWindow(app,"DepthBins");
        end

        % Value changed function: ChannelSelectionforPlottingEditField
        function ChannelSelectionforPlottingEditFieldValueChanged(app, event)
            %% Prepare Plots
            [PlotInfo,SpikeTimes,SpikePositions,SpikeAmplitude,SpikeCluster,~,~,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value] = Event_Spikes_Prepare_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.SpikeRateNumBinsEditField.Value,"Kilosort");

            if strcmp(app.AnalysisTypeDropDown.Value,"Spike Map")
                
                if ~strcmp(app.ClustertoshowDropDown.Value,"All") && ~strcmp(app.ClustertoshowDropDown.Value,"Non")
                    Spikes_Plot_Spike_Times("Eventrelated",app.rgbMatrix,PlotInfo.Time,SpikeTimes,SpikePositions,SpikeCluster,SpikeAmplitude,app.Mainapp.Data.Spikes.ChannelPosition,app.UIAxes,app.numCluster,"Non",[],[],PlotInfo.ChannelsToPlot,app.Mainapp.Data.Info.ChannelSpacing)
                end

                Spikes_Plot_Spike_Times("Eventrelated",app.rgbMatrix,PlotInfo.Time,SpikeTimes,SpikePositions,SpikeCluster,SpikeAmplitude,app.Mainapp.Data.Spikes.ChannelPosition,app.UIAxes,app.numCluster,app.ClustertoshowDropDown.Value,[],[],PlotInfo.ChannelsToPlot,app.Mainapp.Data.Info.ChannelSpacing)
                Event_Spikes_Plot_Spike_Rate(app.Mainapp.Data,PlotInfo.Time,"BinsizeChangeInitial",app.rgbMatrix,SpikeTimes,SpikePositions,SpikeCluster,length(PlotInfo.EventNr(1):PlotInfo.EventNr(2)),app.ClustertoshowDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.UIAxes_3,app.UIAxes_5,app.Mainapp.Data.Spikes.ChannelPosition,app.Mainapp.Data.Info.NativeSamplingRate,PlotInfo.ChannelsToPlot)
                if ~strcmp(app.ClustertoshowDropDown.Value,'Non') && ~strcmp(app.ClustertoshowDropDown.Value,'All')
                    Event_Spikes_Plot_Spike_Rate(app.Mainapp.Data,PlotInfo.Time,"NewCluster",app.rgbMatrix,SpikeTimes,SpikePositions,SpikeCluster,length(PlotInfo.EventNr(1):PlotInfo.EventNr(2)),app.ClustertoshowDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.UIAxes_3,app.UIAxes_5,app.Mainapp.Data.Spikes.ChannelPosition,app.Mainapp.Data.Info.NativeSamplingRate,PlotInfo.ChannelsToPlot)
                end
            elseif strcmp(app.AnalysisTypeDropDown.Value,"Spike Rate Heatmap")
                Event_Spikes_Plot_Heatmap_Spike_Rate(SpikeTimes,SpikePositions,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,PlotInfo.time_bin_size,PlotInfo.depth_edges,PlotInfo.time_edges,length(PlotInfo.EventNr(1):PlotInfo.EventNr(2)),0,app.BaselineNormalizeCheckBox.Value,PlotInfo.NormWindow,app.ClustertoshowDropDown.Value,SpikeCluster,app.rgbMatrix,PlotInfo.ChannelsToPlot,app.Mainapp.Data.Info.ChannelSpacing,"Kilosort")
                Event_Spikes_Plot_Spike_Rate(app.Mainapp.Data,PlotInfo.Time,"BinsizeChangeInitial",app.rgbMatrix,SpikeTimes,SpikePositions,SpikeCluster,length(PlotInfo.EventNr(1):PlotInfo.EventNr(2)),app.ClustertoshowDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.UIAxes_3,app.UIAxes_5,app.Mainapp.Data.Spikes.ChannelPosition,app.Mainapp.Data.Info.NativeSamplingRate,PlotInfo.ChannelsToPlot)
                if ~strcmp(app.ClustertoshowDropDown.Value,'Non') && ~strcmp(app.ClustertoshowDropDown.Value,'All')
                    Event_Spikes_Plot_Spike_Rate(app.Mainapp.Data,PlotInfo.Time,"NewCluster",app.rgbMatrix,SpikeTimes,SpikePositions,SpikeCluster,length(PlotInfo.EventNr(1):PlotInfo.EventNr(2)),app.ClustertoshowDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.UIAxes_3,app.UIAxes_5,app.Mainapp.Data.Spikes.ChannelPosition,app.Mainapp.Data.Info.NativeSamplingRate,PlotInfo.ChannelsToPlot)
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EventRelatedKilosortSpikeAnalysisUIFigure = uifigure('Visible', 'off');
            app.EventRelatedKilosortSpikeAnalysisUIFigure.Name = 'Event Related Kilosort Spike Analysis';
            app.EventRelatedKilosortSpikeAnalysisUIFigure.Icon = 'Logo.png';
            app.SnapshotMenu = uimenu(app.EventRelatedKilosortSpikeAnalysisUIFigure);
            app.SnapshotMenu.Text = '   Snapshot   ';
            app.SaveSnapshotofMainWindowMenu = uimenu(app.SnapshotMenu);
            app.SaveSnapshotofMainWindowMenu.Text = 'Save Snapshot of Main Window';
            app.PNGMenu = uimenu(app.SaveSnapshotofMainWindowMenu);
            app.PNGMenu.MenuSelectedFcn = createCallbackFcn(app, @PNGMenuSelected, true);
            app.PNGMenu.Text = 'PNG';
            app.JPGMenu = uimenu(app.SaveSnapshotofMainWindowMenu);
            app.JPGMenu.MenuSelectedFcn = createCallbackFcn(app, @JPGMenuSelected, true);
            app.JPGMenu.Text = 'JPG';
            app.UIAxes_3 = uiaxes(app.EventRelatedKilosortSpikeAnalysisUIFigure);
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes = uiaxes(app.EventRelatedKilosortSpikeAnalysisUIFigure);
            title(app.UIAxes, 'Title')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes_5 = uiaxes(app.EventRelatedKilosortSpikeAnalysisUIFigure);
            xlabel(app.UIAxes_5, 'X')
            zlabel(app.UIAxes_5, 'Z')
            app.Panel = uipanel(app.EventRelatedKilosortSpikeAnalysisUIFigure);
            app.SpikeRateNumBinsEditFieldLabel = uilabel(app.Panel);
            app.SpikeRateNumBinsEditFieldLabel.Text = {'Spike Rate'; 'Num Bins'};
            app.SpikeRateNumBinsEditField = uieditfield(app.Panel, 'text');
            app.SpikeRateNumBinsEditField.ValueChangedFcn = createCallbackFcn(app, @SpikeRateNumBinsEditFieldValueChanged, true);
            app.SpikeRateNumBinsEditField.Value = '300';
            app.ClustertoShowDropDownLabel = uilabel(app.Panel);
            app.ClustertoShowDropDownLabel.Text = 'Cluster to Show';
            app.ClustertoshowDropDown = uidropdown(app.Panel);
            app.ClustertoshowDropDown.Items = {};
            app.ClustertoshowDropDown.ValueChangedFcn = createCallbackFcn(app, @ClustertoshowDropDownValueChanged, true);
            app.ClustertoshowDropDown.Value = {};
            app.TextArea = uitextarea(app.Panel);
            app.EventRangeEditFieldLabel = uilabel(app.Panel);
            app.EventRangeEditFieldLabel.Text = 'Event Range';
            app.EventRangeEditField = uieditfield(app.Panel, 'text');
            app.EventRangeEditField.ValueChangedFcn = createCallbackFcn(app, @EventRangeEditFieldValueChanged, true);
            app.BaselineWindowStartStopinsEditFieldLabel = uilabel(app.Panel);
            app.BaselineWindowStartStopinsEditFieldLabel.Text = {'Baseline Window'; '[Start,Stop] in s'};
            app.BaselineWindowStartStopinsEditField = uieditfield(app.Panel, 'text');
            app.BaselineWindowStartStopinsEditField.ValueChangedFcn = createCallbackFcn(app, @BaselineWindowStartStopinsEditFieldValueChanged, true);
            app.BaselineNormalizeCheckBox = uicheckbox(app.Panel);
            app.BaselineNormalizeCheckBox.ValueChangedFcn = createCallbackFcn(app, @BaselineNormalizeCheckBoxValueChanged, true);
            app.BaselineNormalizeCheckBox.Text = '  Baseline Normalize';
            app.AnalysisTypeDropDownLabel = uilabel(app.Panel);
            app.AnalysisTypeDropDownLabel.Text = 'Analysis Type';
            app.AnalysisTypeDropDown = uidropdown(app.Panel);
            app.AnalysisTypeDropDown.Items = {'Spike Map', 'Spike Rate Heatmap'};
            app.AnalysisTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @AnalysisTypeDropDownValueChanged, true);
            app.AnalysisTypeDropDown.Value = 'Spike Map';
            app.ChannelSelectionforPlottingEditFieldLabel = uilabel(app.Panel);
            app.ChannelSelectionforPlottingEditFieldLabel.Text = {'Channel Selection'; 'for Plotting'};
            app.ChannelSelectionforPlottingEditField = uieditfield(app.Panel, 'text');
            app.ChannelSelectionforPlottingEditField.ValueChangedFcn = createCallbackFcn(app, @ChannelSelectionforPlottingEditFieldValueChanged, true);
            app.KilosortEventRelatedAnalysisWindowLabel = uilabel(app.EventRelatedKilosortSpikeAnalysisUIFigure);
            app.KilosortEventRelatedAnalysisWindowLabel.Text = 'Kilosort Event Related Analysis Window';
            app.Image = uiimage(app.EventRelatedKilosortSpikeAnalysisUIFigure);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodToolboxLabel = uilabel(app.EventRelatedKilosortSpikeAnalysisUIFigure);
            app.NeuromodToolboxLabel.Text = 'Neuromod Toolbox';
            app.CloseButton = uibutton(app.EventRelatedKilosortSpikeAnalysisUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.ContextMenu = uicontextmenu(app.EventRelatedKilosortSpikeAnalysisUIFigure);
            app.ChangeTimeBinSizeMenu = uimenu(app.ContextMenu);
            app.ChangeTimeBinSizeMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeTimeBinSizeMenuSelected, true);
            app.ChangeTimeBinSizeMenu.Text = 'Change Time Bin Size';
            app.ChangeDepthBinSizeMenu = uimenu(app.ContextMenu);
            app.ChangeDepthBinSizeMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeDepthBinSizeMenuSelected, true);
            app.ChangeDepthBinSizeMenu.Text = 'Change Depth Bin Size';
            % Assign app.ContextMenu
            app.UIAxes.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.EventRelatedKilosortSpikeAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Events_Kilosort_Spike_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.EventRelatedKilosortSpikeAnalysisUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.EventRelatedKilosortSpikeAnalysisUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EventRelatedKilosortSpikeAnalysisUIFigure)
        end
    end
end