classdef SpikeTrgAvgAskPrepro < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpikeTrgAvgPreproUIFigure  matlab.ui.Figure
        CloseButton                matlab.ui.control.Button
        NeuromodToolboxLabel       matlab.ui.control.Label
        Image                      matlab.ui.control.Image
        PreprocessingWindowLabel   matlab.ui.control.Label
        TextArea                   matlab.ui.control.TextArea
        ProceedButton              matlab.ui.control.Button
        DownsampleCheckBox         matlab.ui.control.CheckBox
        LowPassFilterCheckBox      matlab.ui.control.CheckBox
        WarningDatawasnotlowdownsampledDonowwithstandardsettingsLabel  matlab.ui.control.Label
        WarningDatawasnotlowpassedfilteredLabel  matlab.ui.control.Label
        Panel                      matlab.ui.container.Panel
        SaveasPreprocessedDataforMainWindowDropDown  matlab.ui.control.DropDown
        SaveasPreprocessedDataforMainWindowDropDownLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        DatatoUse = []; % Description
        Downsampled = [];
        LowPass = [];
        SpiketrgAvgPrepro = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, DatatoUse, Downsampled, LowPass)
            movegui(app.SpikeTrgAvgPreproUIFigure, 'center');

            app.DatatoUse = DatatoUse; % Description
            app.Downsampled = Downsampled;
            app.LowPass = LowPass;
            
            if strcmp(DatatoUse,'Preprocessed')
                if app.LowPass == 1
                    app.LowPassFilterCheckBox.Enable = "off";
                    app.WarningDatawasnotlowpassedfilteredLabel.Text = "Data is already low pass filtered";
                else
                    app.LowPassFilterCheckBox.Enable = "on";
                end
            else
                app.LowPassFilterCheckBox.Enable = "on";
            end

            if Downsampled == 1
                app.DownsampleCheckBox.Enable = "off";
                app.WarningDatawasnotlowdownsampledDonowwithstandardsettingsLabel.Text = "Data is already downsampled";
            else
                app.DownsampleCheckBox.Enable = "on";
            end
        end

        % Button pushed function: ProceedButton
        function ProceedButtonPushed(app, event)

            if app.LowPassFilterCheckBox.Value == 1
                app.SpiketrgAvgPrepro.LowPass = 1;
            else
                app.SpiketrgAvgPrepro.LowPass = 0;
            end

            if app.DownsampleCheckBox.Value == 1
                app.SpiketrgAvgPrepro.Downsample = 1;
            else
                app.SpiketrgAvgPrepro.Downsample = 0;
            end

            app.SpiketrgAvgPrepro.SavePreproData = app.SaveasPreprocessedDataforMainWindowDropDown.Value;
           
            % Resume execution to return the output
            uiresume(app.SpikeTrgAvgPreproUIFigure);
           
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpikeTrgAvgPreproUIFigure = uifigure('Visible', 'off');
            app.SpikeTrgAvgPreproUIFigure.Name = 'Spike Trg Avg Prepro';
            app.SpikeTrgAvgPreproUIFigure.Icon = 'Logo.png';
            app.Panel = uipanel(app.SpikeTrgAvgPreproUIFigure);
            app.SaveasPreprocessedDataforMainWindowDropDownLabel = uilabel(app.Panel);
            app.SaveasPreprocessedDataforMainWindowDropDownLabel.Text = {'Save as Preprocessed Data'; 'for Main Window'};
            app.SaveasPreprocessedDataforMainWindowDropDown = uidropdown(app.Panel);
            app.SaveasPreprocessedDataforMainWindowDropDown.Items = {'No', 'Yes'};
            app.SaveasPreprocessedDataforMainWindowDropDown.Value = 'No';
            app.WarningDatawasnotlowpassedfilteredLabel = uilabel(app.SpikeTrgAvgPreproUIFigure);
            app.WarningDatawasnotlowpassedfilteredLabel.Text = {'Warning: Data was not low passed filtered!'; 'Do now with standard settings?'};
            app.WarningDatawasnotlowdownsampledDonowwithstandardsettingsLabel = uilabel(app.SpikeTrgAvgPreproUIFigure);
            app.WarningDatawasnotlowdownsampledDonowwithstandardsettingsLabel.Text = {'Warning: Data was not low downsampled!'; 'Do now with standard settings?'};
            app.LowPassFilterCheckBox = uicheckbox(app.SpikeTrgAvgPreproUIFigure);
            app.LowPassFilterCheckBox.Text = '  Low Pass Filter';
            app.DownsampleCheckBox = uicheckbox(app.SpikeTrgAvgPreproUIFigure);
            app.DownsampleCheckBox.Text = '    Downsample';
            app.ProceedButton = uibutton(app.SpikeTrgAvgPreproUIFigure, 'push');
            app.ProceedButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedButtonPushed, true);
            app.ProceedButton.Text = 'Proceed';
            app.TextArea = uitextarea(app.SpikeTrgAvgPreproUIFigure);
            app.TextArea.Value = {'Warning:'; ''; 'Data to compute spike triggered average with is not low pass filtered and/or downsampled.'; 'Select low pass filter to compute spike triggered LFP, otherwise spikes data will also be part of computation!'; 'Not downsampling can take very long and requires a lot of memory depending on the number of spikes.'};
            app.PreprocessingWindowLabel = uilabel(app.SpikeTrgAvgPreproUIFigure);
            app.PreprocessingWindowLabel.Text = 'Preprocessing Window';
            app.Image = uiimage(app.SpikeTrgAvgPreproUIFigure);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodToolboxLabel = uilabel(app.SpikeTrgAvgPreproUIFigure);
            app.NeuromodToolboxLabel.Text = 'Neuromod Toolbox';
            app.CloseButton = uibutton(app.SpikeTrgAvgPreproUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.SpikeTrgAvgPreproUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpikeTrgAvgAskPrepro(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SpikeTrgAvgPreproUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.SpikeTrgAvgPreproUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpikeTrgAvgPreproUIFigure)
        end
    end
end