classdef Spike_Extraction_AskforHighPass < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PreproSTAWindowUIFigure     matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        RightPanel                  matlab.ui.container.Panel
        TextArea                    matlab.ui.control.TextArea
        ProceedButton               matlab.ui.control.Button
        Image                       matlab.ui.control.Image
        HighPassFilterforSpikeExtractionLabel  matlab.ui.control.Label
        CloseButton                 matlab.ui.control.Button
        NeuromodLabel               matlab.ui.control.Label
        FilterOptionsPanel          matlab.ui.container.Panel
        SaveasnewPreprocessedDatasetDropDown  matlab.ui.control.DropDown
        SaveasnewPreprocessedDatasetDropDownLabel  matlab.ui.control.Label
        FilterOrderEditField        matlab.ui.control.EditField
        FilterOrderEditFieldLabel   matlab.ui.control.Label
        CutoffFrequencyHzEditField  matlab.ui.control.EditField
        CutoffFrequencyHzEditFieldLabel  matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        HighPassFilterSettings = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, HighPassFilterSettings)
            movegui(app.PreproSTAWindowUIFigure, 'center');
            app.HighPassFilterSettings = HighPassFilterSettings;
        end

        % Button pushed function: ProceedButton
        function ProceedButtonPushed(app, event)

            if isempty(app.CutoffFrequencyHzEditField.Value) || isempty(app.FilterOrderEditField.Value)
                msgbox("Either coutoff frequency or filter order field is empty. Please fill in all fields and try again.")
                return;
            end

            app.HighPassFilterSettings.Cutoff = app.CutoffFrequencyHzEditField.Value;
            app.HighPassFilterSettings.FilterOrder = app.FilterOrderEditField.Value;
            app.HighPassFilterSettings.SaveFilter = app.SaveasnewPreprocessedDatasetDropDown.Value;
            
            % Resume execution to return the output
            uiresume(app.PreproSTAWindowUIFigure);            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app)
        end

        % Value changed function: CutoffFrequencyHzEditField
        function CutoffFrequencyHzEditFieldValueChanged(app, event)
            [app.CutoffFrequencyHzEditField.Value] = Utility_SimpleCheckInputs(app.CutoffFrequencyHzEditField.Value,"One",'300',0,0);
        end

        % Value changed function: FilterOrderEditField
        function FilterOrderEditFieldValueChanged(app, event)
            [app.FilterOrderEditField.Value] = Utility_SimpleCheckInputs(app.FilterOrderEditField.Value,"One",'6',0,0);
        end

        % Value changed function: SaveasnewPreprocessedDatasetDropDown
        function SaveasnewPreprocessedDatasetDropDownValueChanged(app, event)
            
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.PreproSTAWindowUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {341, 341};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PreproSTAWindowUIFigure = uifigure('Visible', 'off');
            app.PreproSTAWindowUIFigure.Name = 'Prepro STA Window';
            app.PreproSTAWindowUIFigure.Icon = 'Logo.png';
            app.PreproSTAWindowUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.PreproSTAWindowUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.FilterOptionsPanel = uipanel(app.RightPanel);
            app.FilterOptionsPanel.Title = 'Filter Options';
            app.CutoffFrequencyHzEditFieldLabel = uilabel(app.FilterOptionsPanel);
            app.CutoffFrequencyHzEditFieldLabel.Text = {'Cutoff Frequency'; '[Hz]'};
            app.CutoffFrequencyHzEditField = uieditfield(app.FilterOptionsPanel, 'text');
            app.CutoffFrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @CutoffFrequencyHzEditFieldValueChanged, true);
            app.CutoffFrequencyHzEditField.Value = '300';
            app.FilterOrderEditFieldLabel = uilabel(app.FilterOptionsPanel);
            app.FilterOrderEditFieldLabel.Text = 'Filter Order';
            app.FilterOrderEditField = uieditfield(app.FilterOptionsPanel, 'text');
            app.FilterOrderEditField.ValueChangedFcn = createCallbackFcn(app, @FilterOrderEditFieldValueChanged, true);
            app.FilterOrderEditField.Value = '6';
            app.SaveasnewPreprocessedDatasetDropDownLabel = uilabel(app.FilterOptionsPanel);
            app.SaveasnewPreprocessedDatasetDropDownLabel.Text = {'Save as new '; 'Preprocessed Dataset'};
            app.SaveasnewPreprocessedDatasetDropDown = uidropdown(app.FilterOptionsPanel);
            app.SaveasnewPreprocessedDatasetDropDown.Items = {'No', 'Yes'};
            app.SaveasnewPreprocessedDatasetDropDown.ValueChangedFcn = createCallbackFcn(app, @SaveasnewPreprocessedDatasetDropDownValueChanged, true);
            app.SaveasnewPreprocessedDatasetDropDown.Value = 'No';
            app.NeuromodLabel = uilabel(app.RightPanel);
            app.NeuromodLabel.Text = 'Neuromod';
            app.CloseButton = uibutton(app.RightPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.HighPassFilterforSpikeExtractionLabel = uilabel(app.RightPanel);
            app.HighPassFilterforSpikeExtractionLabel.Text = 'High Pass Filter for Spike Extraction';
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = 'Logo.png';
            app.ProceedButton = uibutton(app.RightPanel, 'push');
            app.ProceedButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedButtonPushed, true);
            app.ProceedButton.Text = 'Proceed';
            app.TextArea = uitextarea(app.RightPanel);
            app.TextArea.Value = {'Warning:'; ''; 'Data to extract spike waveforms from is not high pass filtered. This screws with scale and amplitude.'; 'Please confirm filter parameters on the right (or change them beforehand) by clicking on proceed and continoue with applying a high pass filter. Selecting ''yes'' in the dropdown menu will save high pass filtered data as new preprocessed dataset.'; ''; 'Standard Filter Settings:'; ''; 'Filter Order: 6'; 'Cutoff Frequency: 300 Hz'};
            % Show the figure after all components are created
            app.PreproSTAWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Spike_Extraction_AskforHighPass(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.PreproSTAWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.PreproSTAWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PreproSTAWindowUIFigure)
        end
    end
end