classdef Autorun_Manager_Select_Template < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SelectAutorunTemplateWindowUIFigure  matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        RightPanel                   matlab.ui.container.Panel
        CloseButton                  matlab.ui.control.Button
        TextArea_2                   matlab.ui.control.TextArea
        TextArea                     matlab.ui.control.TextArea
        NeuromodLabel                matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        SelectAutorunTemplateWindowLabel  matlab.ui.control.Label
        ProceedButton                matlab.ui.control.Button
        SelectTemplateDropDown       matlab.ui.control.DropDown
        SelectTemplateDropDownLabel  matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        AutorunApp = []; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function startupFcn(app, AutorunApp)
            movegui(app.SelectAutorunTemplateWindowUIFigure, 'center');

            app.AutorunApp = AutorunApp;
            
            PathToSearch = strcat(app.AutorunApp.Mainapp.executableFolder,'\Modules\MISC\Default Autorun Configs (do not edit!)\');

            [stringArray] = Utility_Extract_Contents_of_Folder(PathToSearch);

            for i = 1:length(stringArray)
                app.SelectTemplateDropDown.Items{i}= convertStringsToChars(stringArray(i));
            end
        end

        % Button pushed function: ProceedButton
        function ProceedButtonPushed(app, event)
            app.AutorunApp.SelectedTemplate = app.SelectTemplateDropDown.Value;
            delete(app);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SelectAutorunTemplateWindowUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {216, 216};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SelectAutorunTemplateWindowUIFigure = uifigure('Visible', 'off');
            app.SelectAutorunTemplateWindowUIFigure.Name = 'Select Autorun Template Window';
            app.SelectAutorunTemplateWindowUIFigure.Icon = 'Logo.png';
            app.SelectAutorunTemplateWindowUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.SelectAutorunTemplateWindowUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.SelectTemplateDropDownLabel = uilabel(app.RightPanel);
            app.SelectTemplateDropDownLabel.Text = 'Select Template';
            app.SelectTemplateDropDown = uidropdown(app.RightPanel);
            app.ProceedButton = uibutton(app.RightPanel, 'push');
            app.ProceedButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedButtonPushed, true);
            app.ProceedButton.Text = 'Proceed';
            app.SelectAutorunTemplateWindowLabel = uilabel(app.RightPanel);
            app.SelectAutorunTemplateWindowLabel.Text = 'Select Autorun Template Window';
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodLabel = uilabel(app.RightPanel);
            app.NeuromodLabel.Text = 'Neuromod';
            app.TextArea = uitextarea(app.RightPanel);
            app.TextArea.Editable = 'off';
            app.TextArea.Enable = 'off';
            app.TextArea.Visible = 'off';
            app.TextArea_2 = uitextarea(app.RightPanel);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Enable = 'off';
            app.TextArea_2.Visible = 'off';
            app.CloseButton = uibutton(app.RightPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.SelectAutorunTemplateWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Autorun_Manager_Select_Template(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SelectAutorunTemplateWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.SelectAutorunTemplateWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SelectAutorunTemplateWindowUIFigure)
        end
    end
end