classdef Autorun_Manager_Show_Function_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Show_Function_WindowUIFigure   matlab.ui.Figure
        NeuromodToolboxLabel           matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        EditAutorunConfigsWindowLabel  matlab.ui.control.Label
        CloseButton                    matlab.ui.control.Button
        LoadfunctionagainButton        matlab.ui.control.Button
        SaveChangesButton              matlab.ui.control.Button
        TextArea                       matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        Path = []; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Path)
            movegui(app.Show_Function_WindowUIFigure, 'center');
            % Open the file and read its content
            
            app.Path = Path;
            fileID = fopen(app.Path, 'r');
            functionContent = fread(fileID, '*char')';
            fclose(fileID);
            
            % Display the content in the Text Area
            app.TextArea.Value = functionContent;
        end

        % Button pushed function: SaveChangesButton
        function SaveChangesButtonPushed(app, event)
            % Get the content from the Text Area
            editedContent = app.TextArea.Value;
            
            % If the content is a cell array (multiple text lines), convert it to a single string
            if iscell(editedContent)
                editedContent = strjoin(editedContent, '\n');
            end

            % Open the file for writing and save the content
            fileID = fopen(app.Path, 'w');
            fprintf(fileID, '%s', editedContent);
            fclose(fileID);

            msgbox("Changes were succesfuly saved.");
        end

        % Button pushed function: LoadfunctionagainButton
        function LoadfunctionagainButtonPushed(app, event)
            Fileident = fopen(app.Path, 'r');
            functionContent = fread(Fileident, '*char')';
            fclose(Fileident);
            
            % Display the content in the Text Area
            app.TextArea.Value = functionContent;
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Show_Function_WindowUIFigure = uifigure('Visible', 'off');
            app.Show_Function_WindowUIFigure.Name = 'Show_Function_Window';
            app.Show_Function_WindowUIFigure.Icon = 'Logo.png';
            app.TextArea = uitextarea(app.Show_Function_WindowUIFigure);
            app.SaveChangesButton = uibutton(app.Show_Function_WindowUIFigure, 'push');
            app.SaveChangesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveChangesButtonPushed, true);
            app.SaveChangesButton.Text = 'Save Changes';
            app.LoadfunctionagainButton = uibutton(app.Show_Function_WindowUIFigure, 'push');
            app.LoadfunctionagainButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfunctionagainButtonPushed, true);
            app.LoadfunctionagainButton.Text = {'Load function'; 'again'};
            app.CloseButton = uibutton(app.Show_Function_WindowUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.EditAutorunConfigsWindowLabel = uilabel(app.Show_Function_WindowUIFigure);
            app.EditAutorunConfigsWindowLabel.Text = 'Edit Autorun Configs Window';
            app.Image = uiimage(app.Show_Function_WindowUIFigure);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodToolboxLabel = uilabel(app.Show_Function_WindowUIFigure);
            app.NeuromodToolboxLabel.Text = 'Neuromod Toolbox';
            % Show the figure after all components are created
            app.Show_Function_WindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Autorun_Manager_Show_Function_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.Show_Function_WindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.Show_Function_WindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Show_Function_WindowUIFigure)
        end
    end
end