classdef Autorun_Manager_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AutorunManagerWindowUIFigure    matlab.ui.Figure
        ManageConfigsMenu               matlab.ui.container.Menu
        ResetSelectedConfigtoDefaultMenu  matlab.ui.container.Menu
        DeleteSelectedConfigMenu        matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        RightPanel                      matlab.ui.container.Panel
        OnlyshowsnonemptyfolderinselecteddirectoryLabel  matlab.ui.control.Label
        FolderContentsLabel             matlab.ui.control.Label
        TextArea_2                      matlab.ui.control.TextArea
        AutorunSettingsPanel            matlab.ui.container.Panel
        ExecuteSelectedConfigButton     matlab.ui.control.Button
        ProbeDesignButton               matlab.ui.control.Button
        CloseFiguresafterPlottingCheckBox  matlab.ui.control.CheckBox
        FoldertoskipEditField           matlab.ui.control.EditField
        FoldertoskipEditFieldLabel      matlab.ui.control.Label
        PictureFormatDropDown           matlab.ui.control.DropDown
        PictureFormatDropDownLabel      matlab.ui.control.Label
        SaveAnalysisFiguresCheckBox     matlab.ui.control.CheckBox
        LoopovermultiplefolderCheckBox  matlab.ui.control.CheckBox
        SelectFolderButton              matlab.ui.control.Button
        CloseButton                     matlab.ui.control.Button
        ConfigListLabel                 matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        NeuromodLabel                   matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        AutorunManagerWindowLabel       matlab.ui.control.Label
        ConfigSelectionPanel            matlab.ui.container.Panel
        SelectdifferentConfigFolderButton  matlab.ui.control.Button
        AddNewConfigButton              matlab.ui.control.Button
        ShowConfiginMatlabButton        matlab.ui.control.Button
        ConfigSelectedDropDown          matlab.ui.control.DropDown
        ConfigSelectedDropDownLabel     matlab.ui.control.Label
        ShowConfiginGUIButton           matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Mainapp = [];
        ConfigFolder = [];
        Foldercontents = [];
        Load_Data_Window_Info = [];
        CostumAutorunConfigSettings = [];
        FunctionOrder = [];
        NumIterations = [];
        LoadedData = [];
        FolderFlag = 0;
        ProbeFlag = 0;
        SelectedTemplate = [];
        TemplateToReset = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
        movegui(app.AutorunManagerWindowUIFigure, 'center');
        
        app.Mainapp = Mainapp;
        
        app.ConfigFolder = strcat(app.Mainapp.executableFolder,'\Autorun Configs\Config_Files');
        app.ExecuteSelectedConfigButton.Enable = "off";
        [app.Foldercontents] = Utility_Extract_Contents_of_Folder(app.ConfigFolder);

        Texttoshow = ["IMPORTANT: Dont change standard folder structure created or rename files to ensure smooth operation for multiple analysis runs. If you want to load Kilosort output data, save the output FOLDER CONTENTS to 'Path_to_your_Recording\Kilosort\kilosort4'. It is autosearched for in this location.";"";"First select a config file you want to execute, set the basic options for the analysis and add a probe design (channel order is optional). Lastly select the folder containing your recording(s). If you checked the box 'Loop over multiple recordings', you have to select a folder containing multiple folder each with one recording. If this checkbox is disable, just select a folder with one recording you want to analyse."];

        Texttoshow = [Texttoshow;"";"All config files are saved at GUI_Path/Autorun Configs/Config_Files.";"";"All configs along with the pipeline operations defined in the selected config are shown in the text area above. One template and example config is available for each recording format supported. With the 'Manage Configs' menu on top you can reset to default. Just select a template file you want to reset the current config file to."];

        Texttoshow = [Texttoshow;"";"Default plot appearance is always applied. If you want to change plot appearance for the autorun, do it in the GUI windows and save as new default."];

        app.TextArea_2.Value = Texttoshow;
        matlabfuncindicies = [];

        for i = 1:length(app.Foldercontents)
            matlabfuncfound = [];
            currentname = [];
            currentname = convertStringsToChars(app.Foldercontents(i));
            if ~isempty(currentname) && length(currentname) > 2
                matlabfuncfound = currentname(end-1:end) == '.m';
                if sum(matlabfuncfound) == 2
                    matlabfuncindicies = [matlabfuncindicies,i];
                end
            end
        end

        %% If config files found
        if ~isempty(matlabfuncindicies)
            ConfigSelectedDropDownItems = cell(length(matlabfuncindicies),1);
            for i = 1:length(matlabfuncindicies)
                ConfigSelectedDropDownItems{i} = convertStringsToChars(app.Foldercontents(matlabfuncindicies(i)));
            end

            app.ConfigSelectedDropDown.Items = ConfigSelectedDropDownItems;
            % Extract Main header infos from config file
            [app.CostumAutorunConfigSettings] = eval(strcat(app.ConfigSelectedDropDown.Value(1:end-2),'("Get Settings")'));

            app.TextArea.Value = ["Path:";"";app.ConfigFolder;"";"Config files found:";"";app.Foldercontents;"";"Order of functions in selected Config:";"";app.CostumAutorunConfigSettings.FunctionOrder];

            % Initialize lower part of the GUI
                if strcmp(app.CostumAutorunConfigSettings.ExtractMultipleRecordings,"on")
                    app.LoopovermultiplefolderCheckBox.Value = 1;
                else
                    app.LoopovermultiplefolderCheckBox.Value = 0;
                end
                if strcmp(app.CostumAutorunConfigSettings.SaveFigures,"on")
                    app.SaveAnalysisFiguresCheckBox.Value = 1;
                else
                    app.SaveAnalysisFiguresCheckBox.Value = 0;
                end
                if strcmp(app.CostumAutorunConfigSettings.DeleteFigureAfterSaving,"on")
                    app.CloseFiguresafterPlottingCheckBox.Value = 1;
                else
                    app.CloseFiguresafterPlottingCheckBox.Value = 0;
                end
        
                if strcmp(app.CostumAutorunConfigSettings.SaveFiguresFormat,"png")
                    app.PictureFormatDropDown.Value = app.PictureFormatDropDown.Items{1};
                elseif strcmp(app.CostumAutorunConfigSettings.SaveFiguresFormat,"svg")
                    app.PictureFormatDropDown.Value = app.PictureFormatDropDown.Items{2};
                elseif strcmp(app.CostumAutorunConfigSettings.SaveFiguresFormat,"fig")
                    app.PictureFormatDropDown.Value = app.PictureFormatDropDown.Items{3};
                end
   
                app.AutorunManagerWindowLabel.Text = strcat("Autorun Config: ",app.CostumAutorunConfigSettings.AutorunConfigName); 
        else
            app.TextArea.Value = "No Matlab files found in selected folder";
            Placeholder{1} = ' ';
            app.ConfigSelectedDropDown.Items = Placeholder;
            return;
        end

        app.CostumAutorunConfigSettings.selected_folder = [];
        app.CostumAutorunConfigSettings.FolderContents = [];


        end

        % Button pushed function: ShowConfiginGUIButton
        function ShowConfiginGUIButtonPushed(app, event)
            Path = strcat(app.ConfigFolder,'\',app.ConfigSelectedDropDown.Value);
            
            Autorun_Manager_Show_Function_Window(Path);
            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: AddNewConfigButton
        function AddNewConfigButtonPushed(app, event)
            %% Create new Config
            cd(app.ConfigFolder);
            app.SelectedTemplate = [];
            
            f2 = Autorun_Manager_Select_Template(app);

            waitfor(f2);

            if isempty(app.SelectedTemplate)
                return;
            end

            Path = strcat(app.Mainapp.executableFolder,'\Modules\MISC\Default Autorun Configs (do not edit!)\',app.SelectedTemplate);

            fileIDDefault = fopen(Path, 'r');
            functionContentDefault = fread(fileIDDefault, '*char')';
            fclose(fileIDDefault);

            % Prompt user for file save location and name
            [file, path] = uiputfile('*.m', 'Save as');
            
            if isempty(file) || isempty(path)
                return;
            end

            % Open the file for writing
            fileID = fopen(fullfile(path,file), 'w');
            
            % Write the string to the file
            fprintf(fileID, '%s\n', functionContentDefault);
            
            % Close the file
            fclose(fileID);

            %% Update Config List

            [app.Foldercontents] = Utility_Extract_Contents_of_Folder(app.ConfigFolder);

            matlabfuncindicies = [];
            for i = 1:length(app.Foldercontents)
                matlabfuncfound = [];
                currentname = [];
                currentname = convertStringsToChars(app.Foldercontents(i));
                if ~isempty(currentname) && length(currentname) > 2
                    matlabfuncfound = currentname(end-1:end) == '.m';
                    if sum(matlabfuncfound) == 2
                        matlabfuncindicies = [matlabfuncindicies,i];
                    end
                end
            end
    
            %% If config Files found
            if ~isempty(matlabfuncindicies)
                ConfigSelectedDropDownItems = cell(length(matlabfuncindicies),1);
                for i = 1:length(matlabfuncindicies)
                    ConfigSelectedDropDownItems{i} = convertStringsToChars(app.Foldercontents(matlabfuncindicies(i)));
                end
                app.ConfigSelectedDropDown.Items = ConfigSelectedDropDownItems;
                % Extract Main header infos from config file
                [app.CostumAutorunConfigSettings] = eval(strcat(app.ConfigSelectedDropDown.Value(1:end-2),'("Get Settings");'));
    
                app.TextArea.Value = ["Path:";"";app.ConfigFolder;"";"Config files found:";"";app.Foldercontents;"";"Order of functions in selected Config:";"";app.CostumAutorunConfigSettings.FunctionOrder];
                % Initialize lower part of the GUI
                if strcmp(app.CostumAutorunConfigSettings.ExtractMultipleRecordings,"on")
                    app.LoopovermultiplefolderCheckBox.Value = 1;
                else
                    app.LoopovermultiplefolderCheckBox.Value = 0;
                end
                if strcmp(app.CostumAutorunConfigSettings.SaveFigures,"on")
                    app.SaveAnalysisFiguresCheckBox.Value = 1;
                else
                    app.SaveAnalysisFiguresCheckBox.Value = 0;
                end
                if strcmp(app.CostumAutorunConfigSettings.DeleteFigureAfterSaving,"on")
                    app.CloseFiguresafterPlottingCheckBox.Value = 1;
                else
                    app.CloseFiguresafterPlottingCheckBox.Value = 0;
                end
        
                if strcmp(app.CostumAutorunConfigSettings.SaveFiguresFormat,"png")
                    app.PictureFormatDropDown.Value = app.PictureFormatDropDown.Items{1};
                elseif strcmp(app.CostumAutorunConfigSettings.SaveFiguresFormat,"svg")
                    app.PictureFormatDropDown.Value = app.PictureFormatDropDown.Items{2};
                elseif strcmp(app.CostumAutorunConfigSettings.SaveFiguresFormat,"fig")
                    app.PictureFormatDropDown.Value = app.PictureFormatDropDown.Items{3};
                end
        
                app.AutorunManagerWindowLabel.Text = app.CostumAutorunConfigSettings.AutorunConfigName; 

            else
                app.TextArea.Value = "No Matlab files found in selected folder";
                Placeholder{1} = ' ';
                app.ConfigSelectedDropDown.Items = Placeholder;
                return;
            end

            msgbox("Config was succesfully created.");
        end

        % Button pushed function: ShowConfiginMatlabButton
        function ShowConfiginMatlabButtonPushed(app, event)
            Path = strcat(app.ConfigFolder,'\',app.ConfigSelectedDropDown.Value);
            
            % Open the file in the editor
            edit(Path);
        end

        % Button pushed function: SelectdifferentConfigFolderButton
        function SelectdifferentConfigFolderButtonPushed(app, event)
            cd(app.Mainapp.executableFolder);
            
            app.ConfigFolder = uigetdir('Select a folder with your recording data');
            
            if app.ConfigFolder == 0
                disp('No folder selected. Exiting.');
                return;
            end

            %% Update Config List

            [app.Foldercontents] = Utility_Extract_Contents_of_Folder(app.ConfigFolder);
 
            matlabfuncindicies = [];
            for i = 1:length(app.Foldercontents)
                matlabfuncfound = [];
                currentname = [];
                currentname = convertStringsToChars(app.Foldercontents(i));
                if ~isempty(currentname) && length(currentname) > 2
                    matlabfuncfound = currentname(end-1:end) == '.m';
                    if sum(matlabfuncfound) == 2
                        matlabfuncindicies = [matlabfuncindicies,i];
                    end
                end
            end
    
            if ~isempty(matlabfuncindicies)
                ConfigSelectedDropDownItems = cell(length(matlabfuncindicies),1);
                for i = 1:length(matlabfuncindicies)
                    ConfigSelectedDropDownItems{i} = convertStringsToChars(app.Foldercontents(matlabfuncindicies(i)));
                end
                app.ConfigSelectedDropDown.Items = ConfigSelectedDropDownItems;
                
                % Extract Main header infos from config file
                [app.CostumAutorunConfigSettings] = eval(strcat(app.ConfigSelectedDropDown.Value(1:end-2),'("Get Settings");'));
    
                app.TextArea.Value = ["Path:";"";app.ConfigFolder;"";"Config files found:";"";app.Foldercontents;"";"Order of functions in selected Config:";"";app.CostumAutorunConfigSettings.FunctionOrder];
                % Initialize lower part of the GUI
                if strcmp(app.CostumAutorunConfigSettings.ExtractMultipleRecordings,"on")
                    app.LoopovermultiplefolderCheckBox.Value = 1;
                else
                    app.LoopovermultiplefolderCheckBox.Value = 0;
                end
                if strcmp(app.CostumAutorunConfigSettings.SaveFigures,"on")
                    app.SaveAnalysisFiguresCheckBox.Value = 1;
                else
                    app.SaveAnalysisFiguresCheckBox.Value = 0;
                end
                if strcmp(app.CostumAutorunConfigSettings.DeleteFigureAfterSaving,"on")
                    app.CloseFiguresafterPlottingCheckBox.Value = 1;
                else
                    app.CloseFiguresafterPlottingCheckBox.Value = 0;
                end
        
                if strcmp(app.CostumAutorunConfigSettings.SaveFiguresFormat,"png")
                    app.PictureFormatDropDown.Value = app.PictureFormatDropDown.Items{1};
                elseif strcmp(app.CostumAutorunConfigSettings.SaveFiguresFormat,"svg")
                    app.PictureFormatDropDown.Value = app.PictureFormatDropDown.Items{2};
                elseif strcmp(app.CostumAutorunConfigSettings.SaveFiguresFormat,"fig")
                    app.PictureFormatDropDown.Value = app.PictureFormatDropDown.Items{3};
                end
        
                app.AutorunManagerWindowLabel.Text = app.CostumAutorunConfigSettings.AutorunConfigName; 
            else
                app.TextArea.Value = "No Matlab files found in selected folder";
                Placeholder{1} = ' ';
                app.ConfigSelectedDropDown.Items = Placeholder;
                return;
            end

            
        end

        % Button pushed function: ExecuteSelectedConfigButton
        function ExecuteSelectedConfigButtonPushed(app, event)
            
            if app.ProbeFlag == 0 || app.FolderFlag == 0
                msgbox("Either Folder or probe selection not found. Please select a folder and enter probe information!");
                return;
            end

            selected_folder=app.CostumAutorunConfigSettings.selected_folder;
            FolderContents=app.CostumAutorunConfigSettings.FolderContents;
            
            [app.CostumAutorunConfigSettings] = eval(strcat(app.ConfigSelectedDropDown.Value(1:end-2),'("Get Settings");'));

            app.CostumAutorunConfigSettings.selected_folder=selected_folder;
            app.CostumAutorunConfigSettings.FolderContents=FolderContents;

            app.CostumAutorunConfigSettings.StartFromFolder = str2double(app.FoldertoskipEditField.Value)+1;

            if app.LoopovermultiplefolderCheckBox.Value == true
                app.CostumAutorunConfigSettings.ExtractMultipleRecordings = "on"; % "on" OR "off"; Set "on" to loop over multiple recordings in a folder (each recording in its own folder within the destination folder selected)
            else
                app.CostumAutorunConfigSettings.ExtractMultipleRecordings = "off"; % "on" OR "off"; Set "on" to loop over multiple recordings in a folder (each recording in its own folder within the destination folder selected)
            end

            if app.SaveAnalysisFiguresCheckBox.Value == true
                app.CostumAutorunConfigSettings.SaveFigures = "on";
            else
                app.CostumAutorunConfigSettings.SaveFigures = "off";
            end

            if app.CloseFiguresafterPlottingCheckBox.Value == true
                app.CostumAutorunConfigSettings.DeleteFigureAfterSaving = "on";
            else
                app.CostumAutorunConfigSettings.DeleteFigureAfterSaving = "off";
            end

            app.CostumAutorunConfigSettings.SaveFiguresFormat = app.PictureFormatDropDown.Value(2:end); % "png" OR "svg" OR "fig"
            % ChannelOrder
            if isempty(app.Load_Data_Window_Info.Channelorder)
                app.CostumAutorunConfigSettings.ExtractRawRecording.ChannelOrder = false;
            else
                app.CostumAutorunConfigSettings.ExtractRawRecording.ChannelOrder = true;
            end

            app.CostumAutorunConfigSettings.ExtractRawRecording.ChannelSpacing = app.Load_Data_Window_Info.ChannelSpacing;

            %% Execute ConfigFile

            Execute_Autorun_Config_Template(app.CostumAutorunConfigSettings,app.CostumAutorunConfigSettings.FunctionOrder,app.Mainapp.executableFolder,app.CostumAutorunConfigSettings.AutorunConfigName,app.Load_Data_Window_Info.Channelorder,app.NumIterations,app.LoadedData);
        end

        % Value changed function: ConfigSelectedDropDown
        function ConfigSelectedDropDownValueChanged(app, event)
                % Extract Main header infos from config file
                TempSelectedFolder = [];
                TempFolderContents = [];

                if isfield(app.CostumAutorunConfigSettings,'selected_folder')
                    if ~isempty(app.CostumAutorunConfigSettings.selected_folder)
                        TempSelectedFolder = app.CostumAutorunConfigSettings.selected_folder;
                    end
                end

                if isfield(app.CostumAutorunConfigSettings,'FolderContents')
                    if ~isempty(app.CostumAutorunConfigSettings.FolderContents)
                        TempFolderContents = app.CostumAutorunConfigSettings.FolderContents;
                    end
                end

                [app.CostumAutorunConfigSettings] = eval(strcat(app.ConfigSelectedDropDown.Value(1:end-2),'("Get Settings");'));
        
                app.CostumAutorunConfigSettings.selected_folder = TempSelectedFolder;
                app.CostumAutorunConfigSettings.FolderContents = TempFolderContents;

                app.TextArea.Value = ["Path:";"";app.ConfigFolder;"";"Config files found:";"";app.Foldercontents;"";"Order of functions in selected Config:";"";app.CostumAutorunConfigSettings.FunctionOrder];
                
                % Initialize lower part of the GUI
                if strcmp(app.CostumAutorunConfigSettings.ExtractMultipleRecordings,"on")
                    app.LoopovermultiplefolderCheckBox.Value = 1;
                else
                    app.LoopovermultiplefolderCheckBox.Value = 0;
                end
                if strcmp(app.CostumAutorunConfigSettings.SaveFigures,"on")
                    app.SaveAnalysisFiguresCheckBox.Value = 1;
                else
                    app.SaveAnalysisFiguresCheckBox.Value = 0;
                end
                if strcmp(app.CostumAutorunConfigSettings.DeleteFigureAfterSaving,"on")
                    app.CloseFiguresafterPlottingCheckBox.Value = 1;
                else
                    app.CloseFiguresafterPlottingCheckBox.Value = 0;
                end
        
                if strcmp(app.CostumAutorunConfigSettings.SaveFiguresFormat,"png")
                    app.PictureFormatDropDown.Value = app.PictureFormatDropDown.Items{1};
                elseif strcmp(app.CostumAutorunConfigSettings.SaveFiguresFormat,"svg")
                    app.PictureFormatDropDown.Value = app.PictureFormatDropDown.Items{2};
                elseif strcmp(app.CostumAutorunConfigSettings.SaveFiguresFormat,"fig")
                    app.PictureFormatDropDown.Value = app.PictureFormatDropDown.Items{3};
                end
        
                app.AutorunManagerWindowLabel.Text = app.CostumAutorunConfigSettings.AutorunConfigName; 

                app.TextArea.Value = ["Path:";"";app.ConfigFolder;"";"Config files found:";"";app.Foldercontents;"";"Order of functions in selected Config:";"";app.CostumAutorunConfigSettings.FunctionOrder];
        end

        % Menu selected function: ResetSelectedConfigtoDefaultMenu
        function ResetSelectedConfigtoDefaultMenuSelected(app, event)
            
            AM = Autorun_Reset_Config_Window(app);
            
            waitfor(AM);

            if isempty(app.TemplateToReset) || sum(isnan(app.TemplateToReset))>0
                disp("No template selected. Returning")
                return;
            end

            Path = strcat(app.Mainapp.executableFolder,'\Modules\MISC\Default Autorun Configs (do not edit!)\',app.TemplateToReset);

            app.TemplateToReset = [];

            fileIDDefault = fopen(Path, 'r');
            functionContentDefault = fread(fileIDDefault, '*char')';
            fclose(fileIDDefault);

            Path = strcat(app.ConfigFolder,'\',app.ConfigSelectedDropDown.Value);
            % Open the file for writing and save the content
            fileID = fopen(Path, 'w');
            fprintf(fileID, '%s', functionContentDefault);
            fclose(fileID);

            [app.CostumAutorunConfigSettings] = eval(strcat(app.ConfigSelectedDropDown.Value(1:end-2),'("Get Settings");'));

            app.TextArea.Value = ["Path:";"";app.ConfigFolder;"";"Config files found:";"";app.Foldercontents;"";"Order of functions in selected Config:";"";app.CostumAutorunConfigSettings.FunctionOrder];

            % Initialize lower part of the GUI
                if strcmp(app.CostumAutorunConfigSettings.ExtractMultipleRecordings,"on")
                    app.LoopovermultiplefolderCheckBox.Value = 1;
                else
                    app.LoopovermultiplefolderCheckBox.Value = 0;
                end
                if strcmp(app.CostumAutorunConfigSettings.SaveFigures,"on")
                    app.SaveAnalysisFiguresCheckBox.Value = 1;
                else
                    app.SaveAnalysisFiguresCheckBox.Value = 0;
                end
                if strcmp(app.CostumAutorunConfigSettings.DeleteFigureAfterSaving,"on")
                    app.CloseFiguresafterPlottingCheckBox.Value = 1;
                else
                    app.CloseFiguresafterPlottingCheckBox.Value = 0;
                end
        
                if strcmp(app.CostumAutorunConfigSettings.SaveFiguresFormat,"png")
                    app.PictureFormatDropDown.Value = app.PictureFormatDropDown.Items{1};
                elseif strcmp(app.CostumAutorunConfigSettings.SaveFiguresFormat,"svg")
                    app.PictureFormatDropDown.Value = app.PictureFormatDropDown.Items{2};
                elseif strcmp(app.CostumAutorunConfigSettings.SaveFiguresFormat,"fig")
                    app.PictureFormatDropDown.Value = app.PictureFormatDropDown.Items{3};
                end
        
                app.AutorunManagerWindowLabel.Text = app.CostumAutorunConfigSettings.AutorunConfigName; 

            msgbox("Config was succesfully resetted to default.");
        end

        % Menu selected function: DeleteSelectedConfigMenu
        function DeleteSelectedConfigMenuSelected(app, event)
            Path = strcat(app.ConfigFolder,'\',app.ConfigSelectedDropDown.Value);
            delete(Path);

            %% Update Config List

            [app.Foldercontents] = Utility_Extract_Contents_of_Folder(app.ConfigFolder);

            matlabfuncindicies = [];
            for i = 1:length(app.Foldercontents)
                matlabfuncfound = [];
                currentname = [];
                currentname = convertStringsToChars(app.Foldercontents(i));
                if ~isempty(currentname) && length(currentname) > 2
                    matlabfuncfound = currentname(end-1:end) == '.m';
                    if sum(matlabfuncfound) == 2
                        matlabfuncindicies = [matlabfuncindicies,i];
                    end
                end
            end
    
            if ~isempty(matlabfuncindicies)
                ConfigSelectedDropDownItems = cell(length(matlabfuncindicies),1);
                for i = 1:length(matlabfuncindicies)
                    ConfigSelectedDropDownItems{i} = convertStringsToChars(app.Foldercontents(matlabfuncindicies(i)));
                end
                app.ConfigSelectedDropDown.Items = ConfigSelectedDropDownItems;

                [app.CostumAutorunConfigSettings] = eval(strcat(app.ConfigSelectedDropDown.Value(1:end-2),'("Get Settings");'));
    
                app.TextArea.Value = ["Path:";"";app.ConfigFolder;"";"Config files found:";"";app.Foldercontents;"";"Order of functions in selected Config:";"";app.CostumAutorunConfigSettings.FunctionOrder];

                % Initialize lower part of the GUI
                if strcmp(app.CostumAutorunConfigSettings.ExtractMultipleRecordings,"on")
                    app.LoopovermultiplefolderCheckBox.Value = 1;
                else
                    app.LoopovermultiplefolderCheckBox.Value = 0;
                end
                if strcmp(app.CostumAutorunConfigSettings.SaveFigures,"on")
                    app.SaveAnalysisFiguresCheckBox.Value = 1;
                else
                    app.SaveAnalysisFiguresCheckBox.Value = 0;
                end
                if strcmp(app.CostumAutorunConfigSettings.DeleteFigureAfterSaving,"on")
                    app.CloseFiguresafterPlottingCheckBox.Value = 1;
                else
                    app.CloseFiguresafterPlottingCheckBox.Value = 0;
                end
        
                if strcmp(app.CostumAutorunConfigSettings.SaveFiguresFormat,"png")
                    app.PictureFormatDropDown.Value = app.PictureFormatDropDown.Items{1};
                elseif strcmp(app.CostumAutorunConfigSettings.SaveFiguresFormat,"svg")
                    app.PictureFormatDropDown.Value = app.PictureFormatDropDown.Items{2};
                elseif strcmp(app.CostumAutorunConfigSettings.SaveFiguresFormat,"fig")
                    app.PictureFormatDropDown.Value = app.PictureFormatDropDown.Items{3};
                end
        
                app.AutorunManagerWindowLabel.Text = app.CostumAutorunConfigSettings.AutorunConfigName; 

            else
                app.TextArea.Value = "No Matlab files found in selected folder";
                Placeholder{1} = ' ';
                app.ConfigSelectedDropDown.Items = Placeholder;
                return;
            end

            msgbox("Selected config file was deleted.");
        end

        % Button pushed function: ProbeDesignButton
        function ProbeDesignButtonPushed(app, event)
         
            Fakeapp.executableFolder = app.Mainapp.executableFolder;
            
            app.Load_Data_Window_Info.ChannelSpacing = [];
            app.Load_Data_Window_Info.Channelorder = [];
            
            h = Probe_Layout_Window(Fakeapp,app);

            waitfor(h);

            if isempty(app.Load_Data_Window_Info.ChannelSpacing)
                app.ProbeFlag = 0;
                
                if strcmp(app.CostumAutorunConfigSettings.ExtractMultipleRecordings,"on")
                    if app.FolderFlag == 1 
                        app.TextArea_2.Value = ["Warning: No channel order or channelspacing selected.";"Num of Iterations: ";num2str(app.NumIterations);"Folder Contents: ";app.CostumAutorunConfigSettings.FolderContents'];
                    else
                        app.TextArea_2.Value = ["Warning: No channel order or channelspacing selected."];
                    end
                else
                    if app.FolderFlag == 1 
                        [stringArray] = Utility_Extract_Contents_of_Folder(app.CostumAutorunConfigSettings.selected_folder);
                        app.TextArea_2.Value = ["Warning: No channel order or channelspacing selected.";"Num of Iterations: ";num2str(app.NumIterations);"Folder Contents: ";stringArray];
                    else
                        app.TextArea_2.Value = ["Warning: No channel order or channelspacing selected."];
                    end
                end

                return;
            else
                app.ProbeFlag = 1;
            end

            if isempty(app.Load_Data_Window_Info.Channelorder)
                app.CostumAutorunConfigSettings.ExtractRawRecording.ChannelOrder = false;
            else
                app.CostumAutorunConfigSettings.ExtractRawRecording.ChannelOrder = true;
            end

            app.CostumAutorunConfigSettings.ExtractRawRecording.ChannelSpacing = app.Load_Data_Window_Info.ChannelSpacing;
            
            % Populate Text area with selected stuff
             if strcmp(app.CostumAutorunConfigSettings.ExtractMultipleRecordings,"on") && app.FolderFlag == 1 
                 if sum(isnan(app.Load_Data_Window_Info.Channelorder)) == 0
                    app.TextArea_2.Value = ["Num of Iterations: ";num2str(app.NumIterations);"Channel Order: "; num2str(app.Load_Data_Window_Info.Channelorder);"Folder Contents: ";app.CostumAutorunConfigSettings.FolderContents'];
                 else
                    app.TextArea_2.Value = ["Num of Iterations: ";num2str(app.NumIterations);"Channel Order: "; "not defined";"Folder Contents: ";app.CostumAutorunConfigSettings.FolderContents'];
                 end
             elseif strcmp(app.CostumAutorunConfigSettings.ExtractMultipleRecordings,"on") && app.FolderFlag == 0
                 if sum(isnan(app.Load_Data_Window_Info.Channelorder)) == 0
                    app.TextArea_2.Value = ["Num of Iterations: ";num2str(app.NumIterations);"Channel Order: "; num2str(app.Load_Data_Window_Info.Channelorder)];
                 else
                    app.TextArea_2.Value = ["Num of Iterations: ";num2str(app.NumIterations);"Channel Order:";"not defined"];
                 end
             end
            
             if strcmp(app.CostumAutorunConfigSettings.ExtractMultipleRecordings,"off") && app.FolderFlag == 1 
                [stringArray] = Utility_Extract_Contents_of_Folder(app.CostumAutorunConfigSettings.selected_folder);
                if sum(isnan(app.Load_Data_Window_Info.Channelorder)) == 0
                    app.TextArea_2.Value = ["Num of Iterations: ";num2str(app.NumIterations);"Channel Order: "; num2str(app.Load_Data_Window_Info.Channelorder);"Folder Contents: ";stringArray];
                else
                    app.TextArea_2.Value = ["Num of Iterations: ";num2str(app.NumIterations);"Channel Order: "; "not defined";"Folder Contents: ";stringArray];
                end
             elseif strcmp(app.CostumAutorunConfigSettings.ExtractMultipleRecordings,"off") && app.FolderFlag == 0 
                 if sum(isnan(app.Load_Data_Window_Info.Channelorder)) == 0
                    app.TextArea_2.Value = ["Num of Iterations: ";num2str(app.NumIterations);"Channel Order: ";num2str(app.Load_Data_Window_Info.Channelorder)];
                 else
                    app.TextArea_2.Value = ["Num of Iterations: ";num2str(app.NumIterations);"Channel Order: ";"not defined"];
                 end
             end            

             if app.ProbeFlag == 1 && app.FolderFlag == 1
                app.ExecuteSelectedConfigButton.Enable = "on";
            end
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            app.NumIterations = [];
            app.CostumAutorunConfigSettings.FolderContents = [];
            app.CostumAutorunConfigSettings.selected_folder = [];
            app.FolderFlag = 0;
            
            [app.CostumAutorunConfigSettings,app.NumIterations,app.LoadedData] = Execute_Autorun_Window_Check_Selected_Folder_and_Channel_Order(app.CostumAutorunConfigSettings,app.CostumAutorunConfigSettings.FunctionOrder);

            % populate Text area
            if strcmp(app.CostumAutorunConfigSettings.ExtractMultipleRecordings,"on")
                if isempty(app.CostumAutorunConfigSettings.FolderContents)
                    app.FolderFlag = 0;
                    if app.ProbeFlag == 1 
                        app.TextArea_2.Value = ["Warning: No folder selected.";"Channel Order: "; num2str(app.Load_Data_Window_Info.Channelorder)];
                    else
                        app.TextArea_2.Value = ["Warning: No channel order or channelspacing selected."];
                    end
                    return;    
                else
                    app.FolderFlag = 1;
                end

                if app.ProbeFlag == 1
                    [stringArray] = Utility_Extract_Contents_of_Folder(app.CostumAutorunConfigSettings.selected_folder);
                    if strcmp(app.CostumAutorunConfigSettings.ExtractRawRecording.RecordingsSystem,"Open Ephys")
                        FolderIndicieWithEphysData = [];
                        for foldercontents = 1:length(stringArray)
                            if contains(stringArray(foldercontents),'Record Node')
                                if isfolder(strcat(app.CostumAutorunConfigSettings.selected_folder,'\',stringArray(foldercontents)))
                                    FolderIndicieWithEphysData = [FolderIndicieWithEphysData,foldercontents];
                                end
                            end
                        end
                        
                        if ~isempty(FolderIndicieWithEphysData)
                            app.TextArea_2.Value = "Selected Folder just contains one recording. Please select a folder with multiple recordings.";
                            return;
                        end
                        if sum(isnan(app.Load_Data_Window_Info.Channelorder)) == 0
                            app.TextArea_2.Value = ["Num of Iterations: ";num2str(app.NumIterations);"Channel Order: "; num2str(app.Load_Data_Window_Info.Channelorder);"Folder Contents: ";stringArray];
                        else
                            app.TextArea_2.Value = ["Num of Iterations: ";num2str(app.NumIterations);"Channel Order: "; "not defined";"Folder Contents: ";stringArray];
                        end
                    else
                        if sum(isnan(app.Load_Data_Window_Info.Channelorder)) == 0
                            app.TextArea_2.Value = ["Num of Iterations: ";num2str(app.NumIterations);"Channel Order: "; num2str(app.Load_Data_Window_Info.Channelorder);"Folder Contents: ";app.CostumAutorunConfigSettings.FolderContents'];
                        else
                            app.TextArea_2.Value = ["Num of Iterations: ";num2str(app.NumIterations);"Channel Order: "; "not defined";"Folder Contents: ";app.CostumAutorunConfigSettings.FolderContents'];
                        end
                    end
                else
                    [stringArray] = Utility_Extract_Contents_of_Folder(app.CostumAutorunConfigSettings.selected_folder);
                    if strcmp(app.CostumAutorunConfigSettings.ExtractRawRecording.RecordingsSystem,"Open Ephys")
                        FolderIndicieWithEphysData = [];
                        for foldercontents = 1:length(stringArray)
                            if contains(stringArray(foldercontents),'Record Node')
                                if isfolder(strcat(app.CostumAutorunConfigSettings.selected_folder,'\',stringArray(foldercontents)))
                                    FolderIndicieWithEphysData = [FolderIndicieWithEphysData,foldercontents];
                                end
                            end
                        end
                        
                        if ~isempty(FolderIndicieWithEphysData)
                            app.TextArea_2.Value = "Selected Folder just contains one recording. Please select a folder with multiple recordings.";
                            return;
                        end
            
                        app.TextArea_2.Value = ["Num of Iterations: ";num2str(app.NumIterations);"Folder Contents: ";stringArray];
                    else
                        app.TextArea_2.Value = ["Num of Iterations: ";num2str(app.NumIterations);"Folder Contents: ";app.CostumAutorunConfigSettings.FolderContents'];
                    end
                end
               
            else
               
                if isempty(app.CostumAutorunConfigSettings.selected_folder)
                    app.FolderFlag = 0;
                    if app.ProbeFlag == 1 
                        if sum(isnan(app.Load_Data_Window_Info.Channelorder)) == 0
                            app.TextArea_2.Value = ["Warning: No folder selected.";"Channel Order: "; num2str(app.Load_Data_Window_Info.Channelorder)];
                        else
                            app.TextArea_2.Value = ["Warning: No folder selected.";"Channel Order: "; "not defined"];
                        end
                    else
                        app.TextArea_2.Value = ["Warning: No channel order or channelspacing selected."];
                    end
                    return; 
                else
                    app.FolderFlag = 1;
                end
                    
                if app.ProbeFlag == 1
                    [stringArray] = Utility_Extract_Contents_of_Folder(app.CostumAutorunConfigSettings.selected_folder);
                    if strcmp(app.CostumAutorunConfigSettings.ExtractRawRecording.RecordingsSystem,"Open Ephys")
                        FolderIndicieWithEphysData = [];
                        for foldercontents = 1:length(stringArray)
                            if contains(stringArray(foldercontents),'Record Node')
                                if isfolder(strcat(app.CostumAutorunConfigSettings.selected_folder,'\',stringArray(foldercontents)))
                                    FolderIndicieWithEphysData = [FolderIndicieWithEphysData,foldercontents];
                                end
                            end
                        end
                        if sum(isnan(app.Load_Data_Window_Info.Channelorder)) == 0
                            app.TextArea_2.Value = ["Num of Iterations: ";num2str(app.NumIterations);"Channel Order: "; num2str(app.Load_Data_Window_Info.Channelorder);"Folder Contents: ";stringArray];
                        else
                            app.TextArea_2.Value = ["Num of Iterations: ";num2str(app.NumIterations);"Channel Order: "; "not defined";"Folder Contents: ";stringArray];
                        end
                    else
                        if sum(isnan(app.Load_Data_Window_Info.Channelorder)) == 0
                            app.TextArea_2.Value = ["Num of Iterations: ";num2str(app.NumIterations);"Channel Order: "; num2str(app.Load_Data_Window_Info.Channelorder);"Folder Contents: ";stringArray];
                        else
                            app.TextArea_2.Value = ["Num of Iterations: ";num2str(app.NumIterations);"Channel Order: "; "not defined";"Folder Contents: ";stringArray];
                        end
                    end
                else  
                    [stringArray] = Utility_Extract_Contents_of_Folder(app.CostumAutorunConfigSettings.selected_folder);
                    if strcmp(app.CostumAutorunConfigSettings.ExtractRawRecording.RecordingsSystem,"Open Ephys")
                        FolderIndicieWithEphysData = [];
                        for foldercontents = 1:length(stringArray)
                            if contains(stringArray(foldercontents),'Record Node')
                                if isfolder(strcat(app.CostumAutorunConfigSettings.selected_folder,'\',stringArray(foldercontents)))
                                    FolderIndicieWithEphysData = [FolderIndicieWithEphysData,foldercontents];
                                end
                            end
                        end

                        app.TextArea_2.Value = ["Num of Iterations: ";num2str(app.NumIterations);"Folder Contents: ";stringArray];
                    else
                        app.TextArea_2.Value = ["Num of Iterations: ";num2str(app.NumIterations);"Folder Contents: ";stringArray];
                    end
                end
            end

            if app.ProbeFlag == 1 && app.FolderFlag == 1
                app.ExecuteSelectedConfigButton.Enable = "on";
            end
        end

        % Value changed function: FoldertoskipEditField
        function FoldertoskipEditFieldValueChanged(app, event)
            value = app.FoldertoskipEditField.Value;
            if isempty(value)
                app.ExecuteSelectedConfigButton.Enable = "off";
            else
                if app.ProbeFlag == 1 && app.FolderFlag == 1
                    app.ExecuteSelectedConfigButton.Enable = "on";
                end
            end
        end

        % Value changed function: LoopovermultiplefolderCheckBox
        function LoopovermultiplefolderCheckBoxValueChanged(app, event)
            if isfield(app.CostumAutorunConfigSettings,"FolderContents")
                app.CostumAutorunConfigSettings.FolderContents = [];
                app.FolderFlag = 0;
            end
            if isfield(app.CostumAutorunConfigSettings,"selected_folder")
                app.CostumAutorunConfigSettings.selected_folder = [];
                app.FolderFlag = 0;
            end
            
            app.FoldertoskipEditField.Value = '0';
            
            value = app.LoopovermultiplefolderCheckBox.Value;

            if value == 1
                app.CostumAutorunConfigSettings.ExtractMultipleRecordings = "on";
            else
                app.CostumAutorunConfigSettings.ExtractMultipleRecordings = "off";
            end

            % populate Text area
            if strcmp(app.CostumAutorunConfigSettings.ExtractMultipleRecordings,"on")
                if isempty(app.CostumAutorunConfigSettings.FolderContents)
                    app.FolderFlag = 0;
                    if app.ProbeFlag == 1 
                        app.TextArea_2.Value = ["Warning: No folder selected.";"Channel Order: "; num2str(app.Load_Data_Window_Info.Channelorder)];
                    else
                        app.TextArea_2.Value = ["Warning: No channel order or channelspacing selected."];
                    end
                    return;    
                else
                    app.FolderFlag = 1;
                end

                if app.ProbeFlag == 1
                    app.TextArea_2.Value = ["Num of Iterations: ";num2str(app.NumIterations);"Channel Order: "; num2str(app.Load_Data_Window_Info.Channelorder);"Folder Contents: ";app.CostumAutorunConfigSettings.FolderContents'];
                else
                    app.TextArea_2.Value = ["Num of Iterations: ";num2str(app.NumIterations);"Folder Contents: ";app.CostumAutorunConfigSettings.FolderContents'];
                end
               
            else
               
                if isempty(app.CostumAutorunConfigSettings.selected_folder)
                    app.FolderFlag = 0;
                    if app.ProbeFlag == 1 
                        app.TextArea_2.Value = ["Warning: No folder selected.";"Channel Order: "; num2str(app.Load_Data_Window_Info.Channelorder)];
                    else
                        app.TextArea_2.Value = ["Warning: No channel order or channelspacing selected."];
                    end
                    return; 
                else
                    app.FolderFlag = 1;
                end
                    
                if app.ProbeFlag == 1
                    [stringArray] = Utility_Extract_Contents_of_Folder(app.CostumAutorunConfigSettings.selected_folder);
                    app.TextArea_2.Value = ["Num of Iterations: ";num2str(app.NumIterations);"Channel Order: "; num2str(app.Load_Data_Window_Info.Channelorder);"Folder Contents: ";stringArray];
                else  
                    [stringArray] = Utility_Extract_Contents_of_Folder(app.CostumAutorunConfigSettings.selected_folder);
                    app.TextArea_2.Value = ["Num of Iterations: ";num2str(app.NumIterations);"Folder Contents: ";stringArray];
                end
            end

            if app.ProbeFlag == 1 && app.FolderFlag == 1
                app.ExecuteSelectedConfigButton.Enable = "on";
            end
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.AutorunManagerWindowUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {706, 706};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AutorunManagerWindowUIFigure = uifigure('Visible', 'off');
            app.AutorunManagerWindowUIFigure.Name = 'Autorun Manager Window';
            app.AutorunManagerWindowUIFigure.Icon = 'Logo.png';
            app.AutorunManagerWindowUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.ManageConfigsMenu = uimenu(app.AutorunManagerWindowUIFigure);
            app.ManageConfigsMenu.Text = '  Manage Configs  ';
            app.ResetSelectedConfigtoDefaultMenu = uimenu(app.ManageConfigsMenu);
            app.ResetSelectedConfigtoDefaultMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetSelectedConfigtoDefaultMenuSelected, true);
            app.ResetSelectedConfigtoDefaultMenu.Text = 'Reset Selected Config to Default';
            app.DeleteSelectedConfigMenu = uimenu(app.ManageConfigsMenu);
            app.DeleteSelectedConfigMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteSelectedConfigMenuSelected, true);
            app.DeleteSelectedConfigMenu.Text = 'Delete Selected Config';
            app.GridLayout = uigridlayout(app.AutorunManagerWindowUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.ConfigSelectionPanel = uipanel(app.RightPanel);
            app.ConfigSelectionPanel.Title = 'Config Selection';
            app.ShowConfiginGUIButton = uibutton(app.ConfigSelectionPanel, 'push');
            app.ShowConfiginGUIButton.ButtonPushedFcn = createCallbackFcn(app, @ShowConfiginGUIButtonPushed, true);
            app.ShowConfiginGUIButton.Text = {'Show Config'; ' in GUI'};
            app.ConfigSelectedDropDownLabel = uilabel(app.ConfigSelectionPanel);
            app.ConfigSelectedDropDownLabel.Text = 'Config Selected';
            app.ConfigSelectedDropDown = uidropdown(app.ConfigSelectionPanel);
            app.ConfigSelectedDropDown.Items = {};
            app.ConfigSelectedDropDown.ValueChangedFcn = createCallbackFcn(app, @ConfigSelectedDropDownValueChanged, true);
            app.ConfigSelectedDropDown.Value = {};
            app.ShowConfiginMatlabButton = uibutton(app.ConfigSelectionPanel, 'push');
            app.ShowConfiginMatlabButton.ButtonPushedFcn = createCallbackFcn(app, @ShowConfiginMatlabButtonPushed, true);
            app.ShowConfiginMatlabButton.Text = {'Show Config'; ' in Matlab'};
            app.AddNewConfigButton = uibutton(app.ConfigSelectionPanel, 'push');
            app.AddNewConfigButton.ButtonPushedFcn = createCallbackFcn(app, @AddNewConfigButtonPushed, true);
            app.AddNewConfigButton.Text = 'Add New Config';
            app.SelectdifferentConfigFolderButton = uibutton(app.ConfigSelectionPanel, 'push');
            app.SelectdifferentConfigFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectdifferentConfigFolderButtonPushed, true);
            app.SelectdifferentConfigFolderButton.Text = {'Select different'; 'Config Folder'};
            app.AutorunManagerWindowLabel = uilabel(app.RightPanel);
            app.AutorunManagerWindowLabel.Text = 'Autorun Manager Window';
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodLabel = uilabel(app.RightPanel);
            app.NeuromodLabel.Text = 'Neuromod';
            app.TextArea = uitextarea(app.RightPanel);
            app.ConfigListLabel = uilabel(app.RightPanel);
            app.ConfigListLabel.Text = 'Config List';
            app.CloseButton = uibutton(app.RightPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.AutorunSettingsPanel = uipanel(app.RightPanel);
            app.AutorunSettingsPanel.Title = 'Autorun Settings';
            app.SelectFolderButton = uibutton(app.AutorunSettingsPanel, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select Folder';
            app.LoopovermultiplefolderCheckBox = uicheckbox(app.AutorunSettingsPanel);
            app.LoopovermultiplefolderCheckBox.ValueChangedFcn = createCallbackFcn(app, @LoopovermultiplefolderCheckBoxValueChanged, true);
            app.LoopovermultiplefolderCheckBox.Text = '   Loop over multiple recordings';
            app.LoopovermultiplefolderCheckBox.Value = true;
            app.SaveAnalysisFiguresCheckBox = uicheckbox(app.AutorunSettingsPanel);
            app.SaveAnalysisFiguresCheckBox.Text = '  Save Analysis Figures';
            app.SaveAnalysisFiguresCheckBox.Value = true;
            app.PictureFormatDropDownLabel = uilabel(app.AutorunSettingsPanel);
            app.PictureFormatDropDownLabel.Text = 'Picture Format';
            app.PictureFormatDropDown = uidropdown(app.AutorunSettingsPanel);
            app.PictureFormatDropDown.Items = {'.png', '.svg', '.figure'};
            app.PictureFormatDropDown.Value = '.png';
            app.FoldertoskipEditFieldLabel = uilabel(app.AutorunSettingsPanel);
            app.FoldertoskipEditFieldLabel.Text = 'Folder to skip';
            app.FoldertoskipEditField = uieditfield(app.AutorunSettingsPanel, 'text');
            app.FoldertoskipEditField.ValueChangedFcn = createCallbackFcn(app, @FoldertoskipEditFieldValueChanged, true);
            app.FoldertoskipEditField.Value = '0';
            app.CloseFiguresafterPlottingCheckBox = uicheckbox(app.AutorunSettingsPanel);
            app.CloseFiguresafterPlottingCheckBox.Text = {'  Close Figures after '; '  Plotting'};
            app.CloseFiguresafterPlottingCheckBox.Value = true;
            app.ProbeDesignButton = uibutton(app.AutorunSettingsPanel, 'push');
            app.ProbeDesignButton.ButtonPushedFcn = createCallbackFcn(app, @ProbeDesignButtonPushed, true);
            app.ProbeDesignButton.Text = ' Probe Design';
            app.ExecuteSelectedConfigButton = uibutton(app.AutorunSettingsPanel, 'push');
            app.ExecuteSelectedConfigButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteSelectedConfigButtonPushed, true);
            app.ExecuteSelectedConfigButton.Text = 'Execute Selected Config';
            app.TextArea_2 = uitextarea(app.RightPanel);
            app.FolderContentsLabel = uilabel(app.RightPanel);
            app.FolderContentsLabel.Text = 'Folder Contents';
            app.OnlyshowsnonemptyfolderinselecteddirectoryLabel = uilabel(app.RightPanel);
            app.OnlyshowsnonemptyfolderinselecteddirectoryLabel.Text = {'Only shows non-empty folder'; 'in selected directory'};
            % Show the figure after all components are created
            app.AutorunManagerWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Autorun_Manager_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.AutorunManagerWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.AutorunManagerWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AutorunManagerWindowUIFigure)
        end
    end
end