classdef Autorun_Reset_Config_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ResetConfigWindowUIFigure  matlab.ui.Figure
        DefaultTemplatetoResetConfigtoDropDown  matlab.ui.control.DropDown
        DefaultTemplatetoResetConfigtoDropDownLabel  matlab.ui.control.Label
        ResetwithselectedTemplateButton  matlab.ui.control.Button
        CloseButton                matlab.ui.control.Button
        NeuromodToolboxLabel       matlab.ui.control.Label
        Image                      matlab.ui.control.Image
        ResetConfigWindowLabel     matlab.ui.control.Label
    end

    
    properties (Access = public)
        AutorunManagerApp = []; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, AutorunManagerApp)
            movegui(app.ResetConfigWindowUIFigure, 'center');

            app.AutorunManagerApp = AutorunManagerApp;

            TemplateFolder = strcat(app.AutorunManagerApp.Mainapp.executableFolder,'\Modules\MISC\Default Autorun Configs (do not edit!)\');

            [Foldercontents] = Utility_Extract_Contents_of_Folder(TemplateFolder);
            
            for i = 1:length(Foldercontents)
                app.DefaultTemplatetoResetConfigtoDropDown.Items{i} = convertStringsToChars(Foldercontents(i));
            end
        end

        % Button pushed function: ResetwithselectedTemplateButton
        function ResetwithselectedTemplateButtonPushed(app, event)
            
            
            app.AutorunManagerApp.TemplateToReset = app.DefaultTemplatetoResetConfigtoDropDown.Value;

            delete(app);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ResetConfigWindowUIFigure = uifigure('Visible', 'off');
            app.ResetConfigWindowUIFigure.Name = 'Reset Config Window';
            app.ResetConfigWindowUIFigure.Icon = 'Logo.png';
            app.ResetConfigWindowLabel = uilabel(app.ResetConfigWindowUIFigure);
            app.ResetConfigWindowLabel.Text = 'Reset Config Window';
            app.Image = uiimage(app.ResetConfigWindowUIFigure);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodToolboxLabel = uilabel(app.ResetConfigWindowUIFigure);
            app.NeuromodToolboxLabel.Text = 'Neuromod Toolbox';
            app.CloseButton = uibutton(app.ResetConfigWindowUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.ResetwithselectedTemplateButton = uibutton(app.ResetConfigWindowUIFigure, 'push');
            app.ResetwithselectedTemplateButton.ButtonPushedFcn = createCallbackFcn(app, @ResetwithselectedTemplateButtonPushed, true);
            app.ResetwithselectedTemplateButton.Text = {'Reset with selected '; 'Template'};
            app.DefaultTemplatetoResetConfigtoDropDownLabel = uilabel(app.ResetConfigWindowUIFigure);
            app.DefaultTemplatetoResetConfigtoDropDownLabel.Text = {'Default Template '; 'to Reset Config to'};
            app.DefaultTemplatetoResetConfigtoDropDown = uidropdown(app.ResetConfigWindowUIFigure);
            % Show the figure after all components are created
            app.ResetConfigWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Autorun_Reset_Config_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ResetConfigWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ResetConfigWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ResetConfigWindowUIFigure)
        end
    end
end