classdef Manage_Modules_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ManageModulesWindowUIFigure  matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        RightPanel                   matlab.ui.container.Panel
        CurrentModulesLabel          matlab.ui.control.Label
        ListofSavedModulesTextArea   matlab.ui.control.TextArea
        ListofAvailableModulesTextAreaLabel  matlab.ui.control.Label
        CloseButton                  matlab.ui.control.Button
        CurrentModulesPanel          matlab.ui.container.Panel
        CurrentModulesLabel_5        matlab.ui.control.Label
        CurrentModulesLabel_4        matlab.ui.control.Label
        CurrentModulesLabel_3        matlab.ui.control.Label
        CurrentModulesLabel_2        matlab.ui.control.Label
        SelecttoSwitchoutLabel_2     matlab.ui.control.Label
        Label_3                      matlab.ui.control.Label
        SelectCheckBox_3             matlab.ui.control.CheckBox
        SelectCheckBox_2             matlab.ui.control.CheckBox
        SelecttoSwitchoutLabel       matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        SelecttoSwitchoutLabel_3     matlab.ui.control.Label
        Label_4                      matlab.ui.control.Label
        SelectCheckBox_4             matlab.ui.control.CheckBox
        SelecttoSwitchoutLabel_4     matlab.ui.control.Label
        Label_5                      matlab.ui.control.Label
        SelectCheckBox_5             matlab.ui.control.CheckBox
        Panel_2                      matlab.ui.container.Panel
        ListBox_2                    matlab.ui.control.ListBox
        Panel                        matlab.ui.container.Panel
        ListBox_5                    matlab.ui.control.ListBox
        Panel_1                      matlab.ui.container.Panel
        ListBox_4                    matlab.ui.control.ListBox
        Panel_3                      matlab.ui.container.Panel
        ListBox                      matlab.ui.control.ListBox
        AllModulesPanel              matlab.ui.container.Panel
        AllModulesLabel              matlab.ui.control.Label
        RestoreStandardButton        matlab.ui.control.Button
        SetasnewdefaultButton        matlab.ui.control.Button
        SwitchandApplytoMainWindowButton  matlab.ui.control.Button
        SelectedModuleDropDown       matlab.ui.control.DropDown
        SelectedModuleDropDownLabel  matlab.ui.control.Label
        CreateNewModuleButton        matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Mainapp = []; 
        Titles = [];
        Module = [];
        AppliedSwitch = 0;
        AppliedToMainapp = 0;
        Create_Module_Window = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            % Move this window in the middle of thescreen
            movegui(app.ManageModulesWindowUIFigure, 'center');
            
            app.Mainapp = Mainapp;

            app = Utility_Change_Light_Dark_Mode(app,"ManageModulesWindow");

            app.ListBox_5.Items = app.Mainapp.ListBox_6.Items;
            app.ListBox_4.Items = app.Mainapp.ListBox_3.Items;
            app.ListBox_2.Items = app.Mainapp.ListBox_2.Items;
            app.ListBox.Items = app.Mainapp.ListBox.Items;

            app.Panel.Title = app.Mainapp.MainPlotAnalysisModulePanel.Title;
            app.Panel_1.Title = app.Mainapp.ContinousDataModulePanel.Title;
            app.Panel_2.Title = app.Mainapp.EventDataModulePanel.Title;
            app.Panel_3.Title = app.Mainapp.SpikeModulePanel_2.Title;

            app.CurrentModulesLabel_2.Text = app.Mainapp.MainPlotAnalysisModulePanel.Title;
            app.CurrentModulesLabel_3.Text = app.Mainapp.ContinousDataModulePanel.Title;
            app.CurrentModulesLabel_4.Text = app.Mainapp.EventDataModulePanel.Title;
            app.CurrentModulesLabel_5.Text = app.Mainapp.SpikeModulePanel_2.Title;

            app.Titles{1} = app.Panel.Title;
            app.Titles{2} = app.Panel_1.Title;
            app.Titles{3} = app.Panel_2.Title;
            app.Titles{4} = app.Panel_3.Title;

            [app.Module,app] = All_Module_Items(app,0,[]);

            texttoshow = ["To add a new module click the respective button and enter the required information in the window that opens. For this you need one or more app windows, a module name, the module options names the user can select and a 'RUN' function, handling which of your app windows open (or which of your functions are executed) when a certain module options name was selected (see RUN functions of other modules for reference or take a look at the Example Module).";"All modules found in All_Module_Items.m function:";""];
        
            for i = 1:length(app.Module)
                texttoshow = [texttoshow;convertCharsToStrings(app.Module{i}.Title)];
                app.SelectedModuleDropDown.Items{i} = app.Module{i}.Title;
            end

            texttoshow = [texttoshow;"";"To switch modules, select a module on the left that you want to replace with the module selected in dropdown menu below. Then click the '<< Switch and Apply to Main Window' button to switch."];
            app.ListofSavedModulesTextArea.Value = texttoshow; 

        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            
            try
                try 
                    if ~isempty(app.Create_Module_Window)
                        delete(app.Create_Module_Window)
                    end
                catch
                    app.Create_Module_Window = [];
                end

                app.Mainapp.ManageModulesWindow = [];
                delete(app);
            catch
                delete(app);
            end

        end

        % Button pushed function: SwitchandApplytoMainWindowButton
        function SwitchandApplytoMainWindowButtonPushed(app, event)
            [app] = Change_Modules_Apply_Module_Change_To_MainWindow(app);          
        end

        % Button pushed function: SetasnewdefaultButton
        function SetasnewdefaultButtonPushed(app, event)
            Change_Modules_Set_as_new_Default(app)
        end

        % Button pushed function: RestoreStandardButton
        function RestoreStandardButtonPushed(app, event)
            Change_Modules_Restore_Default(app);
        end

        % Value changed function: SelectCheckBox_2
        function SelectCheckBox_2ValueChanged(app, event)
            value = app.SelectCheckBox_2.Value;
            
        end

        % Button pushed function: CreateNewModuleButton
        function CreateNewModuleButtonPushed(app, event)
            app.Create_Module_Window = Add_New_Module_Window(app,app.Mainapp);
            
            % Path = strcat(app.Mainapp.executableFolder,'\Modules\1. Main_Window_Functions\Manage_Modules_Functions\All_Module_Items.m');
            % 
            % % Open the file in the editor
            % edit(Path);
        end

        % Close request function: ManageModulesWindowUIFigure
        function ManageModulesWindowUIFigureCloseRequest(app, event)
            try
                try 
                    if ~isempty(app.Create_Module_Window)
                        delete(app.Create_Module_Window)
                    end
                catch
                    app.Create_Module_Window = [];
                end

                app.Mainapp.ManageModulesWindow = [];
                delete(app);
            catch
                delete(app);
            end
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ManageModulesWindowUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {624, 624};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ManageModulesWindowUIFigure = uifigure('Visible', 'off');
            app.ManageModulesWindowUIFigure.Name = 'Manage Modules Window';
            app.ManageModulesWindowUIFigure.Icon = 'Logo.png';
            app.ManageModulesWindowUIFigure.CloseRequestFcn = createCallbackFcn(app, @ManageModulesWindowUIFigureCloseRequest, true);
            app.ManageModulesWindowUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.ManageModulesWindowUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.AllModulesPanel = uipanel(app.RightPanel);
            app.AllModulesPanel.Title = 'All Modules';
            app.CreateNewModuleButton = uibutton(app.AllModulesPanel, 'push');
            app.CreateNewModuleButton.ButtonPushedFcn = createCallbackFcn(app, @CreateNewModuleButtonPushed, true);
            app.CreateNewModuleButton.Text = 'Create New Module';
            app.SelectedModuleDropDownLabel = uilabel(app.AllModulesPanel);
            app.SelectedModuleDropDownLabel.Text = 'Selected Module';
            app.SelectedModuleDropDown = uidropdown(app.AllModulesPanel);
            app.SelectedModuleDropDown.Items = {};
            app.SelectedModuleDropDown.Value = {};
            app.SwitchandApplytoMainWindowButton = uibutton(app.AllModulesPanel, 'push');
            app.SwitchandApplytoMainWindowButton.ButtonPushedFcn = createCallbackFcn(app, @SwitchandApplytoMainWindowButtonPushed, true);
            app.SwitchandApplytoMainWindowButton.Text = {'<< Switch and '; 'Apply to Main Window'};
            app.SetasnewdefaultButton = uibutton(app.AllModulesPanel, 'push');
            app.SetasnewdefaultButton.ButtonPushedFcn = createCallbackFcn(app, @SetasnewdefaultButtonPushed, true);
            app.SetasnewdefaultButton.Text = 'Set as new default';
            app.RestoreStandardButton = uibutton(app.AllModulesPanel, 'push');
            app.RestoreStandardButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreStandardButtonPushed, true);
            app.RestoreStandardButton.Text = 'Restore Standard';
            app.AllModulesLabel = uilabel(app.AllModulesPanel);
            app.AllModulesLabel.Text = 'All Modules';
            app.CurrentModulesPanel = uipanel(app.RightPanel);
            app.CurrentModulesPanel.Title = 'Current Modules';
            app.Panel_3 = uipanel(app.CurrentModulesPanel);
            app.Panel_3.Title = 'asd';
            app.ListBox = uilistbox(app.Panel_3);
            app.ListBox.Items = {};
            app.ListBox.Value = {};
            app.Panel_1 = uipanel(app.CurrentModulesPanel);
            app.Panel_1.Title = 'dasd';
            app.ListBox_4 = uilistbox(app.Panel_1);
            app.ListBox_4.Items = {};
            app.ListBox_4.Value = {};
            app.Panel = uipanel(app.CurrentModulesPanel);
            app.Panel.Title = 'dasda';
            app.ListBox_5 = uilistbox(app.Panel);
            app.ListBox_5.Items = {};
            app.ListBox_5.Value = {};
            app.Panel_2 = uipanel(app.CurrentModulesPanel);
            app.Panel_2.Title = 'asdsa';
            app.ListBox_2 = uilistbox(app.Panel_2);
            app.ListBox_2.Items = {};
            app.ListBox_2.Value = {};
            app.SelectCheckBox_5 = uicheckbox(app.CurrentModulesPanel);
            app.SelectCheckBox_5.Text = ' Select';
            app.Label_5 = uilabel(app.CurrentModulesPanel);
            app.Label_5.Text = '>>';
            app.SelecttoSwitchoutLabel_4 = uilabel(app.CurrentModulesPanel);
            app.SelecttoSwitchoutLabel_4.Text = {'Select to'; 'Switch out'};
            app.SelectCheckBox_4 = uicheckbox(app.CurrentModulesPanel);
            app.SelectCheckBox_4.Text = ' Select';
            app.Label_4 = uilabel(app.CurrentModulesPanel);
            app.Label_4.Text = '>>';
            app.SelecttoSwitchoutLabel_3 = uilabel(app.CurrentModulesPanel);
            app.SelecttoSwitchoutLabel_3.Text = {'Select to'; 'Switch out'};
            app.Label_2 = uilabel(app.CurrentModulesPanel);
            app.Label_2.Text = '>>';
            app.SelecttoSwitchoutLabel = uilabel(app.CurrentModulesPanel);
            app.SelecttoSwitchoutLabel.Text = {'Select to'; 'Switch out'};
            app.SelectCheckBox_2 = uicheckbox(app.CurrentModulesPanel);
            app.SelectCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @SelectCheckBox_2ValueChanged, true);
            app.SelectCheckBox_2.Text = ' Select';
            app.SelectCheckBox_3 = uicheckbox(app.CurrentModulesPanel);
            app.SelectCheckBox_3.Text = ' Select';
            app.Label_3 = uilabel(app.CurrentModulesPanel);
            app.Label_3.Text = '>>';
            app.SelecttoSwitchoutLabel_2 = uilabel(app.CurrentModulesPanel);
            app.SelecttoSwitchoutLabel_2.Text = {'Select to'; 'Switch out'};
            app.CurrentModulesLabel_2 = uilabel(app.CurrentModulesPanel);
            app.CurrentModulesLabel_2.Text = 'Current Modules';
            app.CurrentModulesLabel_3 = uilabel(app.CurrentModulesPanel);
            app.CurrentModulesLabel_3.Text = 'Current Modules';
            app.CurrentModulesLabel_4 = uilabel(app.CurrentModulesPanel);
            app.CurrentModulesLabel_4.Text = 'Current Modules';
            app.CurrentModulesLabel_5 = uilabel(app.CurrentModulesPanel);
            app.CurrentModulesLabel_5.Text = 'Current Modules';
            app.CloseButton = uibutton(app.RightPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.ListofAvailableModulesTextAreaLabel = uilabel(app.RightPanel);
            app.ListofAvailableModulesTextAreaLabel.Text = 'List of Available Modules';
            app.ListofSavedModulesTextArea = uitextarea(app.RightPanel);
            app.CurrentModulesLabel = uilabel(app.RightPanel);
            app.CurrentModulesLabel.Text = 'Current Modules';
            % Show the figure after all components are created
            app.ManageModulesWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Manage_Modules_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ManageModulesWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ManageModulesWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ManageModulesWindowUIFigure)
        end
    end
end