classdef Axon_Viewer_Select_Spike < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AxonViewerSpikeSelectionUIFigure  matlab.ui.Figure
        TakeOnlySpikeChannelasSeedinAxonViewerCheckBox  matlab.ui.control.CheckBox
        SpikeSelectionLabel            matlab.ui.control.Label
        SelectionFilterLabel           matlab.ui.control.Label
        TextArea                       matlab.ui.control.TextArea
        SpikeSelectionPanel            matlab.ui.container.Panel
        SpikeNrEditField               matlab.ui.control.EditField
        SpikeNrEditFieldLabel          matlab.ui.control.Label
        SelectionFilterPanel           matlab.ui.container.Panel
        SpikeAmplitudemVempytfornonEditField  matlab.ui.control.EditField
        SpikeAmplitudemVLabel          matlab.ui.control.Label
        SpikeChannelEditField          matlab.ui.control.EditField
        SpikeChannelEditFieldLabel     matlab.ui.control.Label
        ClusterDropDown                matlab.ui.control.DropDown
        ClusterDropDownLabel           matlab.ui.control.Label
        JumpToSpikeinMainWindowButton  matlab.ui.control.Button
        CloseButton                    matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Mainapp = [] % Description
        CurrentNumSpikes = [];
        PreviouslyMarkedSpike = [];
        SelectedSpike = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            movegui(app.AxonViewerSpikeSelectionUIFigure, 'north');

            app.Mainapp = Mainapp;

            app = Utility_Change_Light_Dark_Mode(app,"AxonViewerSelectSpikes");

            app.TakeOnlySpikeChannelasSeedinAxonViewerCheckBox.Value = app.Mainapp.PlotAppearance.MainWindow.Data.TakeSpikeAsSeed;

            if ~strcmp(app.Mainapp.Data.Info.SpikeType,"Non") && ~strcmp(app.Mainapp.Data.Info.SpikeType,"Internal") % Sorting
                app.SpikeChannelEditField.Value = '';
                app.SpikeChannelEditField.Enable = 'off';
                Uniqueclus = unique(app.Mainapp.Data.Spikes.SpikeCluster);
                for t = 1:length(Uniqueclus)
                    app.ClusterDropDown.Items{t} = convertStringsToChars(num2str(Uniqueclus(t)));
                end
            end

            Module_MainWindow_Show_Spike_Selector_Waveforms(app,"FullPlot");

            texttoshow{1} = 'Select a spike to jump to that particular time point in the current main window plot. This can be used as a start point for the "Axon Viewer" window to mark the trajectory of a spike over a multielectrode array. Note: When you jump to the spike time point in the main plot (while plotting data as individual data lines), the selected spike will be at the first sample at the left corner of the plot!';
            texttoshow{2} = strcat('Number of spikes in currently selected channel : ',app.SpikeChannelEditField.Value,': ',num2str(app.CurrentNumSpikes));
            texttoshow{3} = strcat('Standard deviation over all channel : ',num2str(app.Mainapp.Data.Info.HighPassStatistics.Std));

            app.TextArea.Value = texttoshow;
        end

        % Value changed function: SpikeChannelEditField
        function SpikeChannelEditFieldValueChanged(app, event)
            app.UIAxes.NextPlot = "replace"; 
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add"; 
            app.UIAxes.Box = "off";

            app.UIAxes.Title.Color = 'k';
            app.UIAxes.XLabel.Color = 'k';
            app.UIAxes.YLabel.Color = 'k';
            ComponentsInWindowColor = [0.85,0.85,0.85];
            app.UIAxes.Color  = ComponentsInWindowColor;
            app.UIAxes.XColor = 'k';  
        
            app.UIAxes.XTickLabelMode = 'auto';  
            app.UIAxes.TickLabelInterpreter = 'none';  % Avoid LaTeX/TeX interpretation if not needed
            
            % Set title, xlabel, ylabel colors
            app.UIAxes.Title.Color = 'k';
            app.UIAxes.XLabel.Color = 'k';
            
            app.UIAxes.YLabel.Color = 'k';
            app.UIAxes.YColor = 'k';

            app.PreviouslyMarkedSpike = [];

            app.SelectedSpike.SpikeTimes = [];

            ChannelSelected = str2double(app.SpikeChannelEditField.Value);
            app.SpikeNrEditField.Value = '1';

            if sum(ismember(ChannelSelected,app.Mainapp.Data.Info.ProbeInfo.ActiveChannel)) == 0
                msgbox("Channel not part of active probe channel! Reffer to probe view window to select active channel numbers (marked in red outline).")
                return;
            end

            Module_MainWindow_Show_Spike_Selector_Waveforms(app,"FullPlot");

            texttoshow{1} = 'Select a spike to jump to that particular time point in the current main window plot. This can be used as a start point for the "Axon Viewer" window to mark the trajectory of a spike over a multielectrode array.';
            texttoshow{2} = strcat('Number of spikes in currently selected channel: ',num2str(app.CurrentNumSpikes));
            texttoshow{3} = strcat('Standard deviation over all channel: ',num2str(app.Mainapp.Data.Info.HighPassStatistics.Std));

            app.TextArea.Value = texttoshow;
        end

        % Value changed function: SpikeNrEditField
        function SpikeNrEditFieldValueChanged(app, event)
            cla(app.UIAxes);
            
            SpikeSelected = str2double(app.SpikeNrEditField.Value);

            if SpikeSelected > app.CurrentNumSpikes
                msgbox(strcat("Current selection only contains ",num2str(app.CurrentNumSpikes)," spikes."));
                return;
            end
            
            Module_MainWindow_Show_Spike_Selector_Waveforms(app,"FullPlot");
            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            if isfield(app.Mainapp.PreviousThreshGrids,'SpikeChannelSelected')
                fieldsToDelete = {'SpikeChannelSelected'};
                % Delete fields
                app.Mainapp.PreviousThreshGrids = rmfield(app.Mainapp.PreviousThreshGrids, fieldsToDelete);
            end

            try
                app.Mainapp.SpikeSelectorWindow = [];
                delete(app);
            catch
                delete(app);
            end
        end

        % Close request function: AxonViewerSpikeSelectionUIFigure
        function AxonViewerSpikeSelectionUIFigureCloseRequest(app, event)
            if isfield(app.Mainapp.PreviousThreshGrids,'SpikeChannelSelected')
                fieldsToDelete = {'SpikeChannelSelected'};
                % Delete fields
                app.Mainapp.PreviousThreshGrids = rmfield(app.Mainapp.PreviousThreshGrids, fieldsToDelete);
            end

            try
                app.Mainapp.SpikeSelectorWindow = [];
                delete(app);
            catch
                delete(app);
            end
        end

        % Button pushed function: JumpToSpikeinMainWindowButton
        function JumpToSpikeinMainWindowButtonPushed(app, event)
            if isempty(app.SelectedSpike.SpikeTimes)
                msgbox("No spike found with current setting!")
                return;
            end

            if ~strcmp(app.Mainapp.DropDown_2.Value,'Spikes')
                app.Mainapp.DropDown_2.Value = 'Spikes';
                app.Mainapp.Plotspikes = "Spikes";
                msgbox("Automatically changed plot selection in main window plot to show spikes. This is necessary when the 'Take Only Spike Channel as Seed in Axon Viewer' checkbox is activated.");
            end

            app.Mainapp.PreviousThreshGrids = [];
            app.Mainapp.PlotThreshGrids = [];
            
            app.Mainapp.PreviousThreshGrids.SpikeChannelSelected = str2double(app.SpikeChannelEditField.Value);
            app.Mainapp.PreviousThreshGrids.T1 = [];
            
            CurrentSpike = app.SelectedSpike.SpikeTimes(str2double(app.SpikeNrEditField.Value));
            
            %if strcmp(app.Mainapp.PlotAppearance.MainWindow.Data.Plottype,"Surf") || strcmp(app.Mainapp.PlotAppearance.MainWindow.Data.Plottype,"Mesh") || strcmp(app.Mainapp.PlotAppearance.MainWindow.Data.Plottype,"AxonViewer") 
            CurrentSpike = CurrentSpike - (1/app.Mainapp.Data.Info.NativeSamplingRate);
            %end
            
            % Calculate absolute differences between time_value and each element of time_vector
            if isfield(app.Mainapp.Data.Info,'DownsampleFactor') && contains(app.Mainapp.DropDown.Value,"Preprocessed Data")
                differences = abs(CurrentSpike - app.Mainapp.Data.TimeDownsampled);
            else
                differences = abs(CurrentSpike - app.Mainapp.Data.Time);
            end
            
            % % Find the index of the minimum difference
            [~, app.Mainapp.CurrentTimePoints] = min(differences);
            
            if isfield(app.Mainapp.Data.Info,'DownsampleFactor') && contains(app.Mainapp.DropDown.Value,"Preprocessed Data")
                if app.Mainapp.Data.TimeDownsampled(end)-app.Mainapp.Data.TimeDownsampled(app.Mainapp.CurrentTimePoints) <= 0.003
                    app.Mainapp.CurrentTimePoints = length(app.Mainapp.Data.TimeDownsampled) - (0.003*app.Mainapp.Data.Info.DownsampledSampleRate);
                end
            else
                if app.Mainapp.Data.Time(end)-app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints) <= 0.003
                    app.Mainapp.CurrentTimePoints = length(app.Mainapp.Data.Time) - (0.003*app.Mainapp.Data.Info.NativeSamplingRate);
                end
            end
            
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            ImageScChannel_handles = findobj(app.Mainapp.UIAxes,'Tag', 'ImageScChannel');
            delete(ImageScChannel_handles);

            app.Mainapp.UIAxes.NextPlot = "replace"; 
            plot(app.Mainapp.UIAxes,0,0);
            app.Mainapp.UIAxes.NextPlot = "add"; 
            app.Mainapp.UIAxes.Box = "off";

            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Subsequent","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            %Organize_Reset_Main_Plot(app.Mainapp,1,0,0,0,app.Mainapp.CurrentTimePoints);

            app.Mainapp.UIAxes.YColor = 'k';
            app.Mainapp.UIAxes.XColor = 'k';
            app.Mainapp.UIAxes.ZColor = 'k';
            app.Mainapp.UIAxes.Title.Color = 'k';
            app.Mainapp.UIAxes.Color = app.Mainapp.PlotAppearance.MainWindow.Data.Color.MainBackground;
            view(app.Mainapp.UIAxes, 2)
            % Colorbar
            if strcmp(app.Mainapp.PlotAppearance.MainWindow.Data.Plottype,"AxonViewer") || strcmp(app.Mainapp.PlotAppearance.MainWindow.Data.Plottype,"Surf") || strcmp(app.Mainapp.PlotAppearance.MainWindow.Data.Plottype,"Mesh")
                cb = colorbar(app.Mainapp.UIAxes, 'Location', 'westoutside');
                cb.Label.String = 'Signal [mV]';
                cb.Color = 'k';
                cmap = colormap(app.Mainapp.UIAxes);
                colormap(app.Mainapp.UIAxes, flipud(cmap));
            end
        end

        % Value changed function: SpikeAmplitudemVempytfornonEditField
        function SpikeAmplitudemVempytfornonEditFieldValueChanged(app, event)
            app.SpikeNrEditField.Value = '1';
            app.SelectedSpike.SpikeTimes = [];

            Module_MainWindow_Show_Spike_Selector_Waveforms(app,"FullPlot");

            texttoshow{1} = 'Select a spike to jump to that particular time point in the current main window plot. This can be used as a start point for the "Axon Viewer" window to mark the trajectory of a spike over a multielectrode array.';
            texttoshow{2} = strcat('Number of spikes in currently selected channel: ',num2str(app.CurrentNumSpikes));
            texttoshow{3} = strcat('Standard deviation over all channel: ',num2str(app.Mainapp.Data.Info.HighPassStatistics.Std));

            app.TextArea.Value = texttoshow;
            
        end

        % Value changed function: ClusterDropDown
        function ClusterDropDownValueChanged(app, event)
            app.UIAxes.NextPlot = "replace"; 
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add"; 
            app.UIAxes.Box = "off";

            app.UIAxes.Title.Color = 'k';
            app.UIAxes.XLabel.Color = 'k';
            app.UIAxes.YLabel.Color = 'k';
            ComponentsInWindowColor = [0.85,0.85,0.85];
            app.UIAxes.Color  = ComponentsInWindowColor;
            app.UIAxes.XColor = 'k';  
        
            app.UIAxes.XTickLabelMode = 'auto';  
            app.UIAxes.TickLabelInterpreter = 'none';  % Avoid LaTeX/TeX interpretation if not needed
            
            % Set title, xlabel, ylabel colors
            app.UIAxes.Title.Color = 'k';
            app.UIAxes.XLabel.Color = 'k';
            
            app.UIAxes.YLabel.Color = 'k';
            app.UIAxes.YColor = 'k';

            app.PreviouslyMarkedSpike = [];

            app.SelectedSpike.SpikeTimes = [];

            ChannelSelected = str2double(app.SpikeChannelEditField.Value);
            app.SpikeNrEditField.Value = '1';

            % if sum(ismember(ChannelSelected,app.Mainapp.Data.Info.ProbeInfo.ActiveChannel)) == 0
            %     msgbox("Channel not part of active probe channel! Reffer to probe view window to select active channel numbers (marked in red outline).")
            %     return;
            % end

            Module_MainWindow_Show_Spike_Selector_Waveforms(app,"FullPlot");

            texttoshow{1} = 'Select a spike to jump to that particular time point in the current main window plot. This can be used as a start point for the "Axon Viewer" window to mark the trajectory of a spike over a multielectrode array.';
            texttoshow{2} = strcat('Number of spikes in currently selected cluster: ',num2str(app.CurrentNumSpikes));
            texttoshow{3} = strcat('Standard deviation over all channel: ',num2str(app.Mainapp.Data.Info.HighPassStatistics.Std));

            app.TextArea.Value = texttoshow;
            
        end

        % Value changed function: 
        % TakeOnlySpikeChannelasSeedinAxonViewerCheckBox
        function TakeOnlySpikeChannelasSeedinAxonViewerCheckBoxValueChanged(app, event)
            value = app.TakeOnlySpikeChannelasSeedinAxonViewerCheckBox.Value;
            app.Mainapp.PlotAppearance.MainWindow.Data.TakeSpikeAsSeed = double(value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AxonViewerSpikeSelectionUIFigure = uifigure('Visible', 'off');
            app.AxonViewerSpikeSelectionUIFigure.Name = 'Axon Viewer Spike Selection';
            app.AxonViewerSpikeSelectionUIFigure.Icon = 'Logo.png';
            app.AxonViewerSpikeSelectionUIFigure.CloseRequestFcn = createCallbackFcn(app, @AxonViewerSpikeSelectionUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.AxonViewerSpikeSelectionUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.CloseButton = uibutton(app.AxonViewerSpikeSelectionUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.JumpToSpikeinMainWindowButton = uibutton(app.AxonViewerSpikeSelectionUIFigure, 'push');
            app.JumpToSpikeinMainWindowButton.ButtonPushedFcn = createCallbackFcn(app, @JumpToSpikeinMainWindowButtonPushed, true);
            app.JumpToSpikeinMainWindowButton.Text = {'Jump To Spike in '; 'Main Window'};
            app.SelectionFilterPanel = uipanel(app.AxonViewerSpikeSelectionUIFigure);
            app.ClusterDropDownLabel = uilabel(app.SelectionFilterPanel);
            app.ClusterDropDownLabel.Text = 'Cluster';
            app.ClusterDropDown = uidropdown(app.SelectionFilterPanel);
            app.ClusterDropDown.Items = {};
            app.ClusterDropDown.ValueChangedFcn = createCallbackFcn(app, @ClusterDropDownValueChanged, true);
            app.ClusterDropDown.Value = {};
            app.SpikeChannelEditFieldLabel = uilabel(app.SelectionFilterPanel);
            app.SpikeChannelEditFieldLabel.Text = 'Spike Channel';
            app.SpikeChannelEditField = uieditfield(app.SelectionFilterPanel, 'text');
            app.SpikeChannelEditField.ValueChangedFcn = createCallbackFcn(app, @SpikeChannelEditFieldValueChanged, true);
            app.SpikeChannelEditField.Value = '1';
            app.SpikeAmplitudemVLabel = uilabel(app.SelectionFilterPanel);
            app.SpikeAmplitudemVLabel.Text = {'Spike Amplitude [mV]'; '(empyt for non)'};
            app.SpikeAmplitudemVempytfornonEditField = uieditfield(app.SelectionFilterPanel, 'text');
            app.SpikeAmplitudemVempytfornonEditField.ValueChangedFcn = createCallbackFcn(app, @SpikeAmplitudemVempytfornonEditFieldValueChanged, true);
            app.SpikeSelectionPanel = uipanel(app.AxonViewerSpikeSelectionUIFigure);
            app.SpikeNrEditFieldLabel = uilabel(app.SpikeSelectionPanel);
            app.SpikeNrEditFieldLabel.Text = 'Spike Nr';
            app.SpikeNrEditField = uieditfield(app.SpikeSelectionPanel, 'text');
            app.SpikeNrEditField.ValueChangedFcn = createCallbackFcn(app, @SpikeNrEditFieldValueChanged, true);
            app.SpikeNrEditField.Value = '1';
            app.TextArea = uitextarea(app.AxonViewerSpikeSelectionUIFigure);
            app.SelectionFilterLabel = uilabel(app.AxonViewerSpikeSelectionUIFigure);
            app.SelectionFilterLabel.Text = 'Selection Filter';
            app.SpikeSelectionLabel = uilabel(app.AxonViewerSpikeSelectionUIFigure);
            app.SpikeSelectionLabel.Text = 'Spike Selection';
            app.TakeOnlySpikeChannelasSeedinAxonViewerCheckBox = uicheckbox(app.AxonViewerSpikeSelectionUIFigure);
            app.TakeOnlySpikeChannelasSeedinAxonViewerCheckBox.ValueChangedFcn = createCallbackFcn(app, @TakeOnlySpikeChannelasSeedinAxonViewerCheckBoxValueChanged, true);
            app.TakeOnlySpikeChannelasSeedinAxonViewerCheckBox.Text = {'   Take Only Spike Channel '; '   as Seed  in Axon Viewer'};
            % Show the figure after all components are created
            app.AxonViewerSpikeSelectionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Axon_Viewer_Select_Spike(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.AxonViewerSpikeSelectionUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.AxonViewerSpikeSelectionUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AxonViewerSpikeSelectionUIFigure)
        end
    end
end