classdef Probe_View_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ProbeViewUIFigure              matlab.ui.Figure
        QuickSelectOptionsMenu         matlab.ui.container.Menu
        EverySecondRowMenu             matlab.ui.container.Menu
        JustLeftColumnMenu             matlab.ui.container.Menu
        JustRightColumnMenu            matlab.ui.container.Menu
        EveryChannelMenu               matlab.ui.container.Menu
        SelectSpecificRowsMenu_2       matlab.ui.container.Menu
        SelectSpecificColumnsMenu      matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        ShowHelpWindowMenu             matlab.ui.container.Menu
        PlotSpikesPositionsMenu        matlab.ui.container.Menu
        ShowSpikePositionsonProbeMenu  matlab.ui.container.Menu
        HideSpikePositionsonProbeMenu  matlab.ui.container.Menu
        SizeByAmplitudeMenu            matlab.ui.container.Menu
        SizeBySpikeNumberMenu          matlab.ui.container.Menu
        ScrollandclicktosetactiveanalysischannelLabel  matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        ShowChannelSpacingCheckBox     matlab.ui.control.CheckBox
        ChannelSelectionMatlabexpressionsLabel  matlab.ui.control.Label
        ChannelSelectionEditField      matlab.ui.control.EditField
        ChangeforWindowDropDown        matlab.ui.control.DropDown
        ChangeforWindowDropDownLabel   matlab.ui.control.Label
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

       
    properties (Access = public)
        Mainapp = []; % Description
        FirstZoomChannel = 1;
        SpikeCircleSizeMethod = "NumSpikes";
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp, Window)
            % Moce this window in the middle of thescreen
            movegui(app.ProbeViewUIFigure, 'east');
            
            app.Mainapp = Mainapp;

            %% Manage plot
            % Clear any previous plots and hold the plot for multiple elements
            cla(app.UIAxes);
            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";
            % Turn off the box around app.UIAxes
            box(app.UIAxes, 'off');
            app.UIAxes.FontSize = 10;
            %yTicks = get(Figure, 'YTickLabel'); % Current y-tick values
            set(app.UIAxes, 'XTickLabel',[]);

  
            %% Difne Window components
            Placeholder = {};
            app.ChangeforWindowDropDown.Items = Placeholder;
            app.ChangeforWindowDropDown.Items{1} = 'All Windows Opened';
            app.ChangeforWindowDropDown.Items{2} = 'Main Window';

            if ~isempty(app.Mainapp.ContStaticSpectrumWindow)
                app.ChangeforWindowDropDown.Items{end+1} = 'Static Spectrum Window';
            end

            if ~isempty(app.Mainapp.PSTHApp)
                app.ChangeforWindowDropDown.Items{end+1} = 'Main Plot Spike Rate';
            end
            if ~isempty(app.Mainapp.SpectralEstApp)
                app.ChangeforWindowDropDown.Items{end+1} = 'Main Plot Power Estimate';
            end
            if ~isempty(app.Mainapp.CSDApp)
                app.ChangeforWindowDropDown.Items{end+1} = 'Main Plot Current Source Density';
            end
            
            if ~isempty(app.Mainapp.ConInternalSpikesWindow)
                app.ChangeforWindowDropDown.Items{end+1} = 'Cont. Spike Analysis';
            end
            if ~isempty(app.Mainapp.ConKilosortSpikesWindow)
                app.ChangeforWindowDropDown.Items{end+1} = 'Cont. Spike Analysis';
            end

            if ~isempty(app.Mainapp.EventInternalSpikesWindow)
                app.ChangeforWindowDropDown.Items{end+1} = 'Event Spike Analysis';
            end
            if ~isempty(app.Mainapp.EventKilosortSpikesWindow)
                app.ChangeforWindowDropDown.Items{end+1} = 'Event Spike Analysis';
            end

            if ~isempty(app.Mainapp.EventLFPERP)
                app.ChangeforWindowDropDown.Items{end+1} = 'Event ERP Window';
            end
            if ~isempty(app.Mainapp.EventLFPCSD)
                app.ChangeforWindowDropDown.Items{end+1} = 'Event CSD Window';
            end
            if ~isempty(app.Mainapp.EventLFPTF)
                app.ChangeforWindowDropDown.Items{end+1} = 'Event Time Frequency Power Window';
            end
            if ~isempty(app.Mainapp.EventLFPSSP)
                app.ChangeforWindowDropDown.Items{end+1} = 'Event Static Spectrum Window';
            end

            if ~isempty(app.Mainapp.PreproArtefactRejection)
                app.ChangeforWindowDropDown.Items{end+1} = 'Prepro Artefact Rejection';
            end
          
            app.ChangeforWindowDropDown.Value = app.ChangeforWindowDropDown.Items{1};
            
            if isfield(app.Mainapp.Data.Info.ProbeInfo,'CompleteAreaNames')
                BrainAreaInfo.CompleteAreaNames = app.Mainapp.Data.Info.ProbeInfo.CompleteAreaNames;
                BrainAreaInfo.ShortAreaNames = app.Mainapp.Data.Info.ProbeInfo.ShortAreaNames;
                BrainAreaInfo.AreaDistanceFromTip = app.Mainapp.Data.Info.ProbeInfo.AreaDistanceFromTip;
            else
                BrainAreaInfo = [];
            end

            if str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows) == 1
                if str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)<32
                    app.FirstZoomChannel = 1;
                else
                    app.FirstZoomChannel = str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)-31;
                end
                app.Mainapp.ProbeViewProperties = Utility_Plot_Interactive_Probe_View(app.UIAxes,app.Mainapp.Data.Info.ChannelSpacing,str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel),str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows),str2double(app.Mainapp.Data.Info.ProbeInfo.HorOffset),str2double(app.Mainapp.Data.Info.ProbeInfo.VertOffset),app.Mainapp.Data.Info.ProbeInfo.OffSetRowsDistance,app.Mainapp.ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.ShowChannelSpacingCheckBox.Value,1,1,[],app.Mainapp.Data.Info.ProbeInfo.OffSetRows,[],app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel,app.Mainapp.Data.Info.ProbeInfo.SwitchLeftRightChannel,app.Mainapp.Data.Info.ProbeInfo.ECoGArray);
            else
                app.FirstZoomChannel = str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)-31;
                app.Mainapp.ProbeViewProperties = Utility_Plot_Interactive_Probe_View(app.UIAxes,app.Mainapp.Data.Info.ChannelSpacing,str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel),str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows),str2double(app.Mainapp.Data.Info.ProbeInfo.HorOffset),str2double(app.Mainapp.Data.Info.ProbeInfo.VertOffset),app.Mainapp.Data.Info.ProbeInfo.OffSetRowsDistance,app.Mainapp.ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.ShowChannelSpacingCheckBox.Value,1,1,[],app.Mainapp.Data.Info.ProbeInfo.OffSetRows,[],app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel,app.Mainapp.Data.Info.ProbeInfo.SwitchLeftRightChannel,app.Mainapp.Data.Info.ProbeInfo.ECoGArray);
            end

            app.Mainapp.ProbeViewProperties = Utility_Plot_Interactive_Probe_View(app.UIAxes,app.Mainapp.Data.Info.ChannelSpacing,str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel),str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows),str2double(app.Mainapp.Data.Info.ProbeInfo.HorOffset),str2double(app.Mainapp.Data.Info.ProbeInfo.VertOffset),app.Mainapp.Data.Info.ProbeInfo.OffSetRowsDistance,app.Mainapp.ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.ShowChannelSpacingCheckBox.Value,1,1,[],app.Mainapp.Data.Info.ProbeInfo.OffSetRows,[],app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel,app.Mainapp.Data.Info.ProbeInfo.SwitchLeftRightChannel,app.Mainapp.Data.Info.ProbeInfo.ECoGArray);
            
            Window = app.ChangeforWindowDropDown.Value;

            %% Initiate Callback
            if isempty(app.UIAxes.ButtonDownFcn)
                app.UIAxes.ButtonDownFcn = @(src1, event1) ProbeViewClickCallback(app, event1,Window);
            end    
            
            % Add ButtonDownFcn to each line object in UIAxis
            lines = findobj(app.UIAxes, 'Type', 'line');
            
            % Add ButtonDownFcn to each line object in UIAxis
            ChannelViewRight = findobj(app.UIAxes,'Tag','ChannelViewRight');
            
            % Add ButtonDownFcn to each line object in UIAxis
            ChannelViewLeft = findobj(app.UIAxes,'Tag','ChannelViewLeft');
            
            % Add ButtonDownFcn to each line object in UIAxis
            GrayProbeFilling = findobj(app.UIAxes,'Tag','GrayProbeFilling');
            
            %% Set the ButtonDownFcn for UIAxes to register clicks on a plotted line directly
            % lines plotted
            for i = 1:numel(lines)
                % Call Lineclicked function if that happens
                lines(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1,Window);
            end
            
            % Channel squares on the right
            for i = 1:numel(ChannelViewRight)
                % Call Lineclicked function if that happens
                ChannelViewRight(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1,Window);
            end
            % Channel squares on the left
            for i = 1:numel(ChannelViewLeft)
                % Call Lineclicked function if that happens
                ChannelViewLeft(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1,Window);
            end
            % Channel squares on the left
            for i = 1:numel(GrayProbeFilling)
                % Call Lineclicked function if that happens
                GrayProbeFilling(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1,Window);
            end
            
            app.ProbeViewUIFigure.WindowScrollWheelFcn = @(src, event) Utility_ProbeView_Mouse_Wheel_CalBack(app,event,str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel));

            %% Create Legend
            % Create a dummy red line plot for the legend
            dummyLine = plot(app.UIAxes2, NaN, NaN, 'Color', 'red', 'LineWidth', 2);
            
            % Create a dummy yellow rectangle using a patch for the legend
            dummyRect = patch(app.UIAxes2, NaN, NaN, 'yellow', 'EdgeColor', 'none');
            
            % Create the legend and position it at the center of the plot
            legend(app.UIAxes2, [dummyLine, dummyRect], {'All Active Channel', 'Current Analysis Channel'}, 'AutoUpdate', 'off');
            
            % Position the legend in the middle of the figure (over the entire figure size)
            legendPosition = [0.12, 0.845, 0.3, 0.03]; % x, y, width, height (normalized)
            set(app.UIAxes2.Legend, 'Position', legendPosition);

            app = Utility_Change_Light_Dark_Mode(app,"Probe_View_Window");

            % overlay spike pos over probe
            if isfield(app.Mainapp.Data,'Spikes') && strcmp(app.Mainapp.Plotspikes,"Spikes")
               ProbeView_Show_Spike_Positions(app.Mainapp.Data,app.Mainapp.ProbeViewProperties,app.UIAxes,app.SpikeCircleSizeMethod)
            else
                SpikeHandles = findobj(app.UIAxes, 'Tag', 'SpikePositions');
                if ~isempty(SpikeHandles)
                    delete(SpikeHandles)
                end
            end

        end

        % Value changed function: ChannelSelectionEditField
        function ChannelSelectionEditFieldValueChanged(app, event)
            
            if ~isfield(app.Mainapp.Data,'Raw') && ~isfield(app.Mainapp.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end
            
            Window = app.ChangeforWindowDropDown.Value;
            
            if contains(app.ChannelSelectionEditField.Value,',')
                if ~contains(app.ChannelSelectionEditField.Value,'[') || ~contains(app.ChannelSelectionEditField.Value,']')
                    app.ChannelSelectionEditField.Value = strcat('[',app.ChannelSelectionEditField.Value,']');
                end
            end

            if ~isempty(app.ChannelSelectionEditField.Value)
                
                try
                    AllSelectedChannel = eval(app.ChannelSelectionEditField.Value);
                catch
                    AllSelectedChannel = app.Mainapp.Data.Info.ProbeInfo.ActiveChannel;
                    app.ChannelSelectionEditField.Value = strcat(num2str(min(AllSelectedChannel)),':',num2str(max(AllSelectedChannel)));
                end
                
                %AllChannel = length(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);

                if isempty(AllSelectedChannel) || sum(ismember(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,AllSelectedChannel)) == 0
                    msgbox("Error: Channel range exceeds existing number of channel! Plotting all channel.")
                    AllSelectedChannel = app.Mainapp.Data.Info.ProbeInfo.ActiveChannel;
                    app.ChannelSelectionEditField.Value = strcat(num2str(min(AllSelectedChannel)),':',num2str(max(AllSelectedChannel)));
                end


                app.Mainapp.ChannelChange = "EditField";
  
                SelectedChannelIndex = zeros(size(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel));
                
                if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 0
                    for i = 1:length(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel)
                        if sum(AllSelectedChannel==app.Mainapp.Data.Info.ProbeInfo.ActiveChannel(i))==1
                            SelectedChannelIndex(i) = 1;
                        end
                    end
                else
                    ReversedActiveChannel = (str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)*str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)+1)-app.Mainapp.Data.Info.ProbeInfo.ActiveChannel;
                    for i = 1:length(ReversedActiveChannel)
                        if sum(AllSelectedChannel==ReversedActiveChannel(i))==1
                            SelectedChannelIndex(i) = 1;
                        end
                    end
                end
                
                if sum(SelectedChannelIndex)>0
                    if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 1
                        TemActiveChannel = flip(sort(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel));
                        app.Mainapp.ActiveChannel = app.Mainapp.Data.Info.ProbeInfo.ActiveChannel(logical(SelectedChannelIndex));
                    else
                        app.Mainapp.ActiveChannel = app.Mainapp.Data.Info.ProbeInfo.ActiveChannel(logical(SelectedChannelIndex));
                    end
                else
                    msgbox("None of the selected channel are defined as active channel.");
                    if isprop(event,'PreviousValue')
                        app.ChannelSelectionEditField.Value = event.PreviousValue;    
                        return;
                    end
                end

                if isfield(app.Mainapp.Data.Info.ProbeInfo,'CompleteAreaNames')
                    BrainAreaInfo.CompleteAreaNames = app.Mainapp.Data.Info.ProbeInfo.CompleteAreaNames;
                    BrainAreaInfo.ShortAreaNames = app.Mainapp.Data.Info.ProbeInfo.ShortAreaNames;
                    BrainAreaInfo.AreaDistanceFromTip = app.Mainapp.Data.Info.ProbeInfo.AreaDistanceFromTip;
                else
                    BrainAreaInfo = [];
                end

                if strcmp(Window,'Main Window') || strcmp(Window,'All Windows Opened') || strcmp(Window,'Main Plot Current Source Density') || strcmp(Window,'Main Plot Power Estimate') || strcmp(Window,'Main Plot Spike Rate') || strcmp(Window,'Main Plot Phase Synchronization')
                    if strcmp(app.Mainapp.PlotAppearance.MainWindow.Data.Plottype,"Imagesc")
                        if isscalar(app.Mainapp.ActiveChannel)
                            msgbox("Warning! More than 1 channel required for ImageSc in Main Window Plot! Please select more than 1 channel in the probe view window and try again. Or disable to update the main plot in the probe view window.");
                            return;
                        end
                    end
                    if strcmp(app.Mainapp.PlotAppearance.MainWindow.Data.Plottype, "Surf")
                        if length(app.Mainapp.ActiveChannel)<3
                            msgbox("Warning! More than 2 channel required for Grid Activity in Main Window Plot! Please select more than 2 channel in the Probe View window and try again. Or disable to update the main plot in the probe view window.");
                            return;
                        end
                    end
                    if strcmp(app.Mainapp.PlotAppearance.MainWindow.Data.Plottype, "AxonViewer")
                        if length(app.Mainapp.ActiveChannel)<3
                            msgbox("Warning! More than 2 channel required for Axon Viewer in Main Window Plot! Please select more than 2 channel in the Probe View window and try again. Or disable to update the main plot in the probe view window.");
                            return;
                        end
                    end

                    % bring events to the foreground
                    h = findobj(app.Mainapp.UIAxes, 'Tag', 'Events'); 
                    if ~isempty(h)
                        delete(h)
                    end
                    h = findobj(app.Mainapp.UIAxes, 'Tag', 'EventLabel'); 
                    if ~isempty(h)
                        delete(h)
                    end

                    if strcmp(app.Mainapp.PlotAppearance.MainWindow.Data.Plottype,"DataLinesGrid")
                        if ~isempty(app.Mainapp.ChannelAxes)
                            for i = 1:length(app.Mainapp.ChannelAxes)
                                cla(app.Mainapp.ChannelAxes{i});
                            end
                        end
                    end

                    app.Mainapp.ChannelChange = "ProbeView";
        
                    Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Subsequent","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
                    
                    if isscalar(app.Mainapp.ActiveChannel)
                        app.Mainapp.UIAxes.YTickMode = 'auto';
                        app.Mainapp.UIAxes.YTickLabelMode = 'auto';
                        ylabel(app.Mainapp.UIAxes,"Signal [mV]");
                    else
                        app.Mainapp.UIAxes.YTick = [];         % Remove Y ticks
                        app.Mainapp.UIAxes.YTickLabel = {};    % Remove Y tick labels
                        ylabel(app.Mainapp.UIAxes,"");
                    end
                end
               
                if strcmp(Window,'Static Spectrum Window') || strcmp(Window,'All Windows Opened')
                    if ~isempty(app.Mainapp.ContStaticSpectrumWindow)
                        if isvalid(app.Mainapp.ContStaticSpectrumWindow)
                            %app.Mainapp.ProbeViewProperties = Utility_Plot_Interactive_Probe_View(app.UIAxes,app.Mainapp.Data.Info.ChannelSpacing,str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel),str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows),str2double(app.Mainapp.Data.Info.ProbeInfo.HorOffset),str2double(app.Mainapp.Data.Info.ProbeInfo.VertOffset),app.Mainapp.Data.Info.ProbeInfo.OffSetRowsDistance,ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,AllActiveChannel,app.ShowChannelSpacingCheckBox.Value,0,1,ChannelClicked,app.Mainapp.Data.Info.ProbeInfo.OffSetRows,TwoRowOffsetDesignHit,app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel,app.Mainapp.Data.Info.ProbeInfo.SwitchLeftRightChannel) 
                            if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,'Band Power over Depth')
                                [app.Mainapp.PowerSpecResults,app.Mainapp.ContStaticSpectrumWindow.BandPower,~] = Continous_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.Mainapp.ContStaticSpectrumWindow.DataSourceDropDown.Value,app.Mainapp.PowerSpecResults,app.Mainapp.ContStaticSpectrumWindow.BandPower,app.Mainapp.ContStaticSpectrumWindow.FrequencyRangeHzEditField.Value,app.Mainapp.ContStaticSpectrumWindow.UIAxes,app.Mainapp.ContStaticSpectrumWindow.UIAxes_2,app.Mainapp.ContStaticSpectrumWindow.TextArea,'All',app.Mainapp.ContStaticSpectrumWindow.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.Mainapp.PreservePlotChannelLocations);
                            else
                                [app.Mainapp.PowerSpecResults,app.Mainapp.ContStaticSpectrumWindow.BandPower,~] = Continous_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.Mainapp.ContStaticSpectrumWindow.DataSourceDropDown.Value,app.Mainapp.PowerSpecResults,app.Mainapp.ContStaticSpectrumWindow.BandPower,app.Mainapp.ContStaticSpectrumWindow.FrequencyRangeHzEditField.Value,app.Mainapp.ContStaticSpectrumWindow.UIAxes,app.Mainapp.ContStaticSpectrumWindow.UIAxes_2,app.Mainapp.ContStaticSpectrumWindow.TextArea,'Just Frequency Bands',app.Mainapp.ContStaticSpectrumWindow.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.Mainapp.PreservePlotChannelLocations);
                            end
                            if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,'Band Power over Depth')
                                cb = colorbar(app.Mainapp.ContStaticSpectrumWindow.UIAxes);
                                cb.Color = 'k';              % Sets tick mark and label color to black
                                cb.Label.String = "Power [dB]";
                                cb.Label.Rotation = 270;
                                %cb.FontSize =  app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeFontSize;
                                cb.Label.Color = 'k';        % Sets the color of the label text
                            else
                                cb = colorbar(app.Mainapp.ContStaticSpectrumWindow.UIAxes);   % Create a colorbar (if it exists)
                                if ~isempty(cb)
                                    delete(cb);                  % Delete the colorbar
                                end
                            end
                            
                            if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,'Band Power Across Grid')
                                Freqs = str2double(strsplit(app.Mainapp.ContStaticSpectrumWindow.FrequencyRangeHzEditField.Value,','));
                                [~,FreqsIndicie(1)] = min(abs(app.Mainapp.ContStaticSpectrumWindow.BandPower.F - Freqs(1)));
                                [~,FreqsIndicie(2)] = min(abs(app.Mainapp.ContStaticSpectrumWindow.BandPower.F - Freqs(2)));
                
                                EventRelatedSpectrum = app.Mainapp.ContStaticSpectrumWindow.BandPower.allPowerEst(:,FreqsIndicie(1):FreqsIndicie(2));
                    
                                [Channelrange] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.Mainapp.ActiveChannel,'MainPlot');
                                EventRelatedSpectrum = EventRelatedSpectrum(Channelrange,:);
                                
                                Ylims = [min(EventRelatedSpectrum,[],'all') max(EventRelatedSpectrum,[],'all')];
                
                                % EventRelatedData is channel by time
                                Module_Main_Window_Plot_Grid_Trace_View(app.Mainapp.ContStaticSpectrumWindow,app.Mainapp.Data,EventRelatedSpectrum,app.Mainapp.ContStaticSpectrumWindow.BandPower.F(FreqsIndicie(1):FreqsIndicie(2))',0,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations,[],app.Mainapp.PlotAppearance.SpectrumWindow.GridPlotType,0,Ylims);
                            end
                        end
                    end
                end
                
                if strcmp(Window,'All Windows Opened') || strcmp(Window,'Cont. Spike Analysis')
                    if ~isempty(app.Mainapp.ConInternalSpikesWindow) || ~isempty(app.Mainapp.ConKilosortSpikesWindow)
                        %app.Mainapp.ProbeViewProperties = Utility_Plot_Interactive_Probe_View(app.UIAxes,app.Mainapp.Data.Info.ChannelSpacing,str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel),str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows),str2double(app.Mainapp.Data.Info.ProbeInfo.HorOffset),str2double(app.Mainapp.Data.Info.ProbeInfo.VertOffset),app.Mainapp.Data.Info.ProbeInfo.OffSetRowsDistance,ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,AllActiveChannel,app.ShowChannelSpacingCheckBox.Value,0,1,ChannelClicked,app.Mainapp.Data.Info.ProbeInfo.OffSetRows,TwoRowOffsetDesignHit,app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel,app.Mainapp.Data.Info.ProbeInfo.SwitchLeftRightChannel);
                        [app] = Utility_ProbeChange_Plot_ContSpikes(app);
                    end
                end
    
                if strcmp(Window,'All Windows Opened') || strcmp(Window,'Event Spike Analysis')
                    if ~isempty(app.Mainapp.EventInternalSpikesWindow) || ~isempty(app.Mainapp.EventKilosortSpikesWindow)
                        %app.Mainapp.ProbeViewProperties = Utility_Plot_Interactive_Probe_View(app.UIAxes,app.Mainapp.Data.Info.ChannelSpacing,str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel),str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows),str2double(app.Mainapp.Data.Info.ProbeInfo.HorOffset),str2double(app.Mainapp.Data.Info.ProbeInfo.VertOffset),app.Mainapp.Data.Info.ProbeInfo.OffSetRowsDistance,ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,AllActiveChannel,app.ShowChannelSpacingCheckBox.Value,0,1,ChannelClicked,app.Mainapp.Data.Info.ProbeInfo.OffSetRows,TwoRowOffsetDesignHit,app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel,app.Mainapp.Data.Info.ProbeInfo.SwitchLeftRightChannel);
                        [app] = Utility_ProbeChange_Plot_EventSpikes(app);
                    end
                end
                
                if strcmp(Window,'All Windows Opened') || strcmp(Window,'Delete Event Trigger Window')
                    if isprop(app.Mainapp,'EventIndiceRejectionWindow')
                        if ~isempty(app.Mainapp.EventIndiceRejectionWindow) && isvalid(app.Mainapp.EventIndiceRejectionWindow)
                            %app.Mainapp.ProbeViewProperties = Utility_Plot_Interactive_Probe_View(app.UIAxes,app.Mainapp.Data.Info.ChannelSpacing,str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel),str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows),str2double(app.Mainapp.Data.Info.ProbeInfo.HorOffset),str2double(app.Mainapp.Data.Info.ProbeInfo.VertOffset),app.Mainapp.Data.Info.ProbeInfo.OffSetRowsDistance,app.Mainapp.ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.ShowChannelSpacingCheckBox.Value,1,1,app.Mainapp.ActiveChannel,app.Mainapp.Data.Info.ProbeInfo.OffSetRows,[],app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel,app.Mainapp.Data.Info.ProbeInfo.SwitchLeftRightChannel,app.Mainapp.Data.Info.ProbeInfo.ECoGArray)
                            %% Extract and plot stim artefacts
                            if ~strcmp(app.Mainapp.EventIndiceRejectionWindow.EventstoPlotDropDown.Value,"Mean over all Trigger")
                                EventToPlot = str2double(app.Mainapp.EventIndiceRejectionWindow.EventstoPlotDropDown.Value);
                            else
                                EventToPlot = 1:length(app.Mainapp.Data.Events{1});
                            end
                
                            Organize_Plot_Trigger_Indices(app.Mainapp.Data,app.Mainapp.EventIndiceRejectionWindow.UIAxes,EventToPlot,app.Mainapp.Data.Info.EventRelatedTime,app.Mainapp.ActiveChannel,app.Mainapp.EventIndiceRejectionWindow.EventstoPlotDropDown.Value,app.Mainapp.EventIndiceRejectionWindow.Slider.Value,app.Mainapp.EventIndiceRejectionWindow.EventChannelforStimulationDropDown.Value,app.Mainapp.EventIndiceRejectionWindow.TimearoundEvent,app.Mainapp.EventIndiceRejectionWindow.DataToExtractFromDropDown.Value,"Raw Event Related Data",app.Mainapp.EventIndiceRejectionWindow.ColorMap)
                        end
                    end
                end
    
                if strcmp(Window,'Event CSD Window') && ~isempty(app.Mainapp.EventLFPCSD) || strcmp(Window,'All Windows Opened') && ~isempty(app.Mainapp.EventLFPCSD)
                    [app] = Utility_ProbeChange_Plot_EventRelatedLFP(app,'CSD');
                end
                if strcmp(Window,'Event ERP Window') && ~isempty(app.Mainapp.EventLFPERP) || strcmp(Window,'All Windows Opened')  && ~isempty(app.Mainapp.EventLFPERP)
                    if strcmp(app.Mainapp.PlotAppearance.ERPWindow.PlotType,"GridTraceView")
                        if ~isempty(app.Mainapp.EventLFPERP.ChannelAxes)
                            for i = 1:length(app.Mainapp.EventLFPERP.ChannelAxes)
                                cla(app.Mainapp.EventLFPERP.ChannelAxes{i});
                            end
                        end
                    end
                    [app] = Utility_ProbeChange_Plot_EventRelatedLFP(app,'ERP');
                end
                if strcmp(Window,'Event Static Spectrum Window') && ~isempty(app.Mainapp.EventLFPSSP) || strcmp(Window,'All Windows Opened') && ~isempty(app.Mainapp.EventLFPSSP)
                    [app] = Utility_ProbeChange_Plot_EventRelatedLFP(app,'EventSpectrum');
                end
                if strcmp(Window,'Event Time Frequency Power Window') && ~isempty(app.Mainapp.EventLFPTF) || strcmp(Window,'All Windows Opened') && ~isempty(app.Mainapp.EventLFPTF)
                    [app] = Utility_ProbeChange_Plot_EventRelatedLFP(app,'TF');
                end
                if strcmp(Window,'Event Phase Synchronization') && ~isempty(app.Mainapp.EventPhaseSynchro) || strcmp(Window,'All Windows Opened') && ~isempty(app.Mainapp.EventPhaseSynchro)
                    [app] = Utility_ProbeChange_Plot_EventRelatedLFP(app,'PhaseSync');
                end
    
                if strcmp(Window,'Prepro Artefact Rejection') || strcmp(Window,'All Windows Opened')
                    if ~isempty(app.Mainapp.PreproArtefactRejection)
                        [~,app.Mainapp.PreproArtefactRejection.StimArtefactInfo] = Preprocess_Extract_and_Plot_Stimulation_Artefact(app.Mainapp.Data, app.Mainapp.PreproArtefactRejection.TimeAroundEventsEditField_2.Value ,app.Mainapp.PreproArtefactRejection.TimeAroundEventsEditField.Value, app.Mainapp.PreproArtefactRejection.EventChannelforStimulationDropDown.Value, app.Mainapp.PreproArtefactRejection.EventstoPlotDropDown.Value, app.Mainapp.PreproArtefactRejection.Slider.Value, app.Mainapp.PreproArtefactRejection.UIAxes, app.Mainapp.ActiveChannel);            
                    end
                end
                
                app.Mainapp.ProbeViewProperties = Utility_Plot_Interactive_Probe_View(app.UIAxes,app.Mainapp.Data.Info.ChannelSpacing,str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel),str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows),str2double(app.Mainapp.Data.Info.ProbeInfo.HorOffset),str2double(app.Mainapp.Data.Info.ProbeInfo.VertOffset),app.Mainapp.Data.Info.ProbeInfo.OffSetRowsDistance,app.Mainapp.ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.ShowChannelSpacingCheckBox.Value,1,1,app.Mainapp.ActiveChannel,app.Mainapp.Data.Info.ProbeInfo.OffSetRows,[],app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel,app.Mainapp.Data.Info.ProbeInfo.SwitchLeftRightChannel,app.Mainapp.Data.Info.ProbeInfo.ECoGArray);
                
            else
                if strcmp(Window,"Main Window")
                    app.Mainapp.ChannelChange = "ProbeView";
                end
            end
            
            app.Mainapp = Utility_Change_Light_Dark_Mode(app.Mainapp,"MainWindow");

            if ~isempty(app.ChannelSelectionEditField.Value)
                if isscalar(AllSelectedChannel)
                    app.Mainapp.UIAxes.YColor = 'k';  
                    app.Mainapp.UIAxes.YTickLabelMode = 'auto';
                    app.Mainapp.UIAxes.XColor = 'k';  
                    app.Mainapp.UIAxes.Title.Color = 'k';  
                else

                end
            end
        end

        % Close request function: ProbeViewUIFigure
        function ProbeViewUIFigureCloseRequest(app, event)
            try
                app.Mainapp.ProbeViewWindowHandle = [];
                
            catch 
                
            end
            try
                app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown
            catch 
                
            end
            delete(app)
        end

        % Menu selected function: EverySecondRowMenu
        function EverySecondRowMenuSelected(app, event)
            if ~isfield(app.Mainapp.Data,'Raw') && ~isfield(app.Mainapp.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end
            
            Window = app.ChangeforWindowDropDown.Value;

            NrChannel = length(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
            
            app.Mainapp.ActiveChannel = [];
            
            if str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows) == 1
                if ~app.Mainapp.Data.Info.ProbeInfo.OffSetRows
                    for nchannel = 1:2:NrChannel
                        if nchannel <= length(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel)-1
                            app.Mainapp.ActiveChannel = [app.Mainapp.ActiveChannel,app.Mainapp.Data.Info.ProbeInfo.ActiveChannel(nchannel)];
                        end
                    end
                    
                else
                    for nchannel = 1:4:NrChannel
                        if nchannel <= length(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel)-1
                            app.Mainapp.ActiveChannel = [app.Mainapp.ActiveChannel,app.Mainapp.Data.Info.ProbeInfo.ActiveChannel(nchannel),app.Mainapp.Data.Info.ProbeInfo.ActiveChannel(nchannel+1)];
                        end
                    end
                end
            elseif str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows) == 2
                for nchannel = 1:4:NrChannel-1
                    if nchannel <= length(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel)
                        app.Mainapp.ActiveChannel = [app.Mainapp.ActiveChannel,app.Mainapp.Data.Info.ProbeInfo.ActiveChannel(nchannel),app.Mainapp.Data.Info.ProbeInfo.ActiveChannel(nchannel+1)];
                    end
                end
            else
                % Array stuff
                AllX = length(unique(app.Mainapp.Data.Info.ProbeInfo.xcoords(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel)));
                AllY = length(unique(app.Mainapp.Data.Info.ProbeInfo.ycoords(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel)));
                ChannelIndicieMatrix = reshape(1:(AllY*AllX), AllX, AllY)'; 
                
                TempActiveChannel = ChannelIndicieMatrix(1:2:end, :);
                app.Mainapp.ActiveChannel = sort(TempActiveChannel(:));
                app.Mainapp.ActiveChannel(~ismember(app.Mainapp.ActiveChannel,app.Mainapp.Data.Info.ProbeInfo.ActiveChannel)) = [];
            end

            if strcmp(Window,'Main Window') || strcmp(Window,'All Windows Opened') || strcmp(Window,'Main Plot Current Source Density') || strcmp(Window,'Main Plot Power Estimate') || strcmp(Window,'Main Plot Spike Rate')
                %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                if strcmp(app.Mainapp.PlotAppearance.MainWindow.Data.Plottype,"DataLinesGrid")
                    if ~isempty(app.Mainapp.ChannelAxes)
                        for i = 1:length(app.Mainapp.ChannelAxes)
                            cla(app.Mainapp.ChannelAxes{i});
                        end
                    end
                end

                Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Subsequent","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
    
                if isscalar(app.Mainapp.ActiveChannel)
                    app.Mainapp.UIAxes.YTickMode = 'auto';
                    app.Mainapp.UIAxes.YTickLabelMode = 'auto';
                    ylabel(app.Mainapp.UIAxes,"Signal [mV]");
                else
                    app.Mainapp.UIAxes.YTick = [];         % Remove Y ticks
                    app.Mainapp.UIAxes.YTickLabel = {};    % Remove Y tick labels
                    ylabel(app.Mainapp.UIAxes,"");
                end
            end

            if strcmp(Window,"Static Spectrum Window") || strcmp(Window,"All Windows Opened")
                if ~isempty(app.Mainapp.ContStaticSpectrumWindow)
                    if isvalid(app.Mainapp.ContStaticSpectrumWindow)
                        if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,'Band Power over Depth')
                            [app.Mainapp.PowerSpecResults,app.Mainapp.ContStaticSpectrumWindow.BandPower,~] = Continous_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.Mainapp.ContStaticSpectrumWindow.DataSourceDropDown.Value,app.Mainapp.PowerSpecResults,app.Mainapp.ContStaticSpectrumWindow.BandPower,app.Mainapp.ContStaticSpectrumWindow.FrequencyRangeHzEditField.Value,app.Mainapp.ContStaticSpectrumWindow.UIAxes,app.Mainapp.ContStaticSpectrumWindow.UIAxes_2,app.Mainapp.ContStaticSpectrumWindow.TextArea,'All',app.Mainapp.ContStaticSpectrumWindow.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.Mainapp.PreservePlotChannelLocations);
                        else
                            if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,'Band Power Across Grid')
                                Freqs = str2double(strsplit(app.Mainapp.ContStaticSpectrumWindow.FrequencyRangeHzEditField.Value,','));
                                [~,FreqsIndicie(1)] = min(abs(app.Mainapp.ContStaticSpectrumWindow.BandPower.F - Freqs(1)));
                                [~,FreqsIndicie(2)] = min(abs(app.Mainapp.ContStaticSpectrumWindow.BandPower.F - Freqs(2)));
                
                                EventRelatedSpectrum = app.Mainapp.ContStaticSpectrumWindow.BandPower.allPowerEst(:,FreqsIndicie(1):FreqsIndicie(2));
                    
                                [Channelrange] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.Mainapp.ActiveChannel,'MainPlot');
                                EventRelatedSpectrum = EventRelatedSpectrum(Channelrange,:);
                                
                                Ylims = [min(EventRelatedSpectrum,[],'all') max(EventRelatedSpectrum,[],'all')];
                
                                % EventRelatedData is channel by time
                                Module_Main_Window_Plot_Grid_Trace_View(app.Mainapp.ContStaticSpectrumWindow,app.Mainapp.Data,EventRelatedSpectrum,app.Mainapp.ContStaticSpectrumWindow.BandPower.F(FreqsIndicie(1):FreqsIndicie(2))',0,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations,[],app.Mainapp.PlotAppearance.SpectrumWindow.GridPlotType,0,Ylims);
                            else
                                [app.Mainapp.PowerSpecResults,app.Mainapp.ContStaticSpectrumWindow.BandPower,~] = Continous_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.Mainapp.ContStaticSpectrumWindow.DataSourceDropDown.Value,app.Mainapp.PowerSpecResults,app.Mainapp.ContStaticSpectrumWindow.BandPower,app.Mainapp.ContStaticSpectrumWindow.FrequencyRangeHzEditField.Value,app.Mainapp.ContStaticSpectrumWindow.UIAxes,app.Mainapp.ContStaticSpectrumWindow.UIAxes_2,app.Mainapp.ContStaticSpectrumWindow.TextArea,'Just Frequency Bands',app.Mainapp.ContStaticSpectrumWindow.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.Mainapp.PreservePlotChannelLocations);
                            end
                        end
                    end
                end
            end

            if strcmp(Window,'Cont. Spike Analysis') || strcmp(Window,'All Windows Opened') 
                
                if ~isempty(app.Mainapp.ConInternalSpikesWindow) || ~isempty(app.Mainapp.ConKilosortSpikesWindow)
                    [app] = Utility_ProbeChange_Plot_ContSpikes(app);
                end
            end

            if strcmp(Window,'Event Spike Analysis') || strcmp(Window,'All Windows Opened') 
                if ~isempty(app.Mainapp.EventInternalSpikesWindow) || ~isempty(app.Mainapp.EventKilosortSpikesWindow)
                    [app] = Utility_ProbeChange_Plot_EventSpikes(app);
                end
            end

            if isfield(app.Mainapp.Data.Info.ProbeInfo,'CompleteAreaNames')
                BrainAreaInfo.CompleteAreaNames = app.Mainapp.Data.Info.ProbeInfo.CompleteAreaNames;
                BrainAreaInfo.ShortAreaNames = app.Mainapp.Data.Info.ProbeInfo.ShortAreaNames;
                BrainAreaInfo.AreaDistanceFromTip = app.Mainapp.Data.Info.ProbeInfo.AreaDistanceFromTip;
            else
                BrainAreaInfo = [];
            end

           if strcmp(Window,'Event ERP Window') || strcmp(Window,'All Windows Opened') || strcmp(Window,'Event CSD Window') || strcmp(Window,'Event CSD Window')|| strcmp(Window,'Event Static Spectrum Window')  || strcmp(Window,'Event Time Frequency Power Window') 
                if ~isempty(app.Mainapp.EventLFPERP) || ~isempty(app.Mainapp.EventLFPCSD) || ~isempty(app.Mainapp.EventLFPSSP) || ~isempty(app.Mainapp.EventLFPTF)
                    %app.Mainapp.ProbeViewProperties = Utility_Plot_Interactive_Probe_View(app.UIAxes,app.Mainapp.Data.Info.ChannelSpacing,str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel),str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows),str2double(app.Mainapp.Data.Info.ProbeInfo.HorOffset),str2double(app.Mainapp.Data.Info.ProbeInfo.VertOffset),app.Mainapp.Data.Info.ProbeInfo.OffSetRowsDistance,app.Mainapp.ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.ShowChannelSpacingCheckBox.Value);
                    if strcmp(Window,'Event CSD Window') && ~isempty(app.Mainapp.EventLFPCSD) || strcmp(Window,'All Windows Opened') & ~isempty(app.Mainapp.EventLFPCSD)
                        [app] = Utility_ProbeChange_Plot_EventRelatedLFP(app,'CSD');
                    end
                    if strcmp(Window,'Event ERP Window') && ~isempty(app.Mainapp.EventLFPERP) || strcmp(Window,'All Windows Opened') && ~isempty(app.Mainapp.EventLFPERP)
                        if strcmp(app.Mainapp.PlotAppearance.ERPWindow.PlotType,"GridTraceView")
                            if ~isempty(app.Mainapp.EventLFPERP.ChannelAxes)
                                for i = 1:length(app.Mainapp.EventLFPERP.ChannelAxes)
                                    cla(app.Mainapp.EventLFPERP.ChannelAxes{i});
                                end
                            end
                        end
                        [app] = Utility_ProbeChange_Plot_EventRelatedLFP(app,'ERP');
                    end
                    if strcmp(Window,'Event Static Spectrum Window') && ~isempty(app.Mainapp.EventLFPSSP) || strcmp(Window,'All Windows Opened') && ~isempty(app.Mainapp.EventLFPSSP)
                        [app] = Utility_ProbeChange_Plot_EventRelatedLFP(app,'EventSpectrum');
                    end
                    if strcmp(Window,'Event Time Frequency Power Window') && ~isempty(app.Mainapp.EventLFPTF) || strcmp(Window,'All Windows Opened') && ~isempty(app.Mainapp.EventLFPTF)
                        [app] = Utility_ProbeChange_Plot_EventRelatedLFP(app,'TF');
                    end
                end
           end

           if strcmp(Window,'Prepro Artefact Rejection') || strcmp(Window,'All Windows Opened')
                if ~isempty(app.Mainapp.PreproArtefactRejection)
                    [~,app.Mainapp.PreproArtefactRejection.StimArtefactInfo] = Preprocess_Extract_and_Plot_Stimulation_Artefact(app.Mainapp.Data, app.Mainapp.PreproArtefactRejection.TimeAroundEventsEditField_2.Value ,app.Mainapp.PreproArtefactRejection.TimeAroundEventsEditField.Value, app.Mainapp.PreproArtefactRejection.EventChannelforStimulationDropDown.Value, app.Mainapp.PreproArtefactRejection.EventstoPlotDropDown.Value, app.Mainapp.PreproArtefactRejection.Slider.Value, app.Mainapp.PreproArtefactRejection.UIAxes, app.Mainapp.ActiveChannel);            
                end
           end

            app.Mainapp.ProbeViewProperties = Utility_Plot_Interactive_Probe_View(app.UIAxes,app.Mainapp.Data.Info.ChannelSpacing,str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel),str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows),str2double(app.Mainapp.Data.Info.ProbeInfo.HorOffset),str2double(app.Mainapp.Data.Info.ProbeInfo.VertOffset),app.Mainapp.Data.Info.ProbeInfo.OffSetRowsDistance,app.Mainapp.ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.ShowChannelSpacingCheckBox.Value,1,1,[],app.Mainapp.Data.Info.ProbeInfo.OffSetRows,[],app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel,app.Mainapp.Data.Info.ProbeInfo.SwitchLeftRightChannel,app.Mainapp.Data.Info.ProbeInfo.ECoGArray);
        end

        % Value changed function: ShowChannelSpacingCheckBox
        function ShowChannelSpacingCheckBoxValueChanged(app, event)
            if ~isfield(app.Mainapp.Data,'Raw') && ~isfield(app.Mainapp.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end
            
            if isfield(app.Mainapp.Data.Info.ProbeInfo,'CompleteAreaNames')
                BrainAreaInfo = app.Mainapp.Data.Info.ProbeInfo.CompleteAreaNames;
            else
                BrainAreaInfo = [];
            end

            AllActiveChannel = app.Mainapp.Data.Info.ProbeInfo.ActiveChannel;
            ActiveChannel = app.Mainapp.ActiveChannel;

            app.Mainapp.PreservePlotChannelLocations = double(app.ShowChannelSpacingCheckBox.Value);
            

            %% Delete ERP Grid
            if isprop(app.Mainapp,'EventLFPERP')
                if ~isempty(app.Mainapp.EventLFPERP)
                    if ~isempty(app.Mainapp.EventLFPERP.ChannelAxes)
                        for k = 1:numel(app.Mainapp.EventLFPERP.ChannelAxes)
                            if isvalid(app.Mainapp.EventLFPERP.ChannelAxes{k})  
                                delete(app.Mainapp.EventLFPERP.ChannelAxes{k});
                            end
                        end
                        app.Mainapp.EventLFPERP.ChannelAxes = [];
                    end
                    if ~isempty(app.Mainapp.EventLFPERP.ChannelGrid)
                        delete(app.Mainapp.EventLFPERP.ChannelGrid)
                    end
        
                    if strcmp(app.Mainapp.PlotAppearance.ERPWindow.PlotType,"GridTraceView")
                        app.Mainapp.EventLFPERP = Module_MainWindow_Initialize_Grid_Trace_Panel(app.Mainapp.EventLFPERP,app.Mainapp,"ERP");
                    end
                end
            end
            %% Event Spectrum Grid
            if isprop(app.Mainapp,'EventLFPSSP')
                if ~isempty(app.Mainapp.EventLFPSSP)
                    if ~isempty(app.Mainapp.EventLFPSSP.ChannelAxes)
                        for k = 1:numel(app.Mainapp.EventLFPSSP.ChannelAxes)
                            if isvalid(app.Mainapp.EventLFPSSP.ChannelAxes{k})  
                                delete(app.Mainapp.EventLFPSSP.ChannelAxes{k});
                            end
                        end
                        app.Mainapp.EventLFPSSP.ChannelAxes = [];
                    end
                    if ~isempty(app.Mainapp.EventLFPSSP.ChannelGrid)
                        delete(app.Mainapp.EventLFPSSP.ChannelGrid)
                    end
        
                    if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Across Grid")
                        app.Mainapp.EventLFPSSP = Module_MainWindow_Initialize_Grid_Trace_Panel(app.Mainapp.EventLFPSSP,app.Mainapp,"StaticEventSpectrum");
                    end
                end
            end
            
            %% Cont Spectrum Grid
            if isprop(app.Mainapp,'ContStaticSpectrumWindow')
                if ~isempty(app.Mainapp.ContStaticSpectrumWindow)
                    if ~isempty(app.Mainapp.ContStaticSpectrumWindow.ChannelAxes)
                        for k = 1:numel(app.Mainapp.ContStaticSpectrumWindow.ChannelAxes)
                            if isvalid(app.Mainapp.ContStaticSpectrumWindow.ChannelAxes{k})  
                                delete(app.Mainapp.ContStaticSpectrumWindow.ChannelAxes{k});
                            end
                        end
                        app.Mainapp.ContStaticSpectrumWindow.ChannelAxes = [];
                    end
                    if ~isempty(app.Mainapp.ContStaticSpectrumWindow.ChannelGrid)
                        delete(app.Mainapp.ContStaticSpectrumWindow.ChannelGrid)
                    end
        
                    if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Across Grid")
                        app.Mainapp.ContStaticSpectrumWindow = Module_MainWindow_Initialize_Grid_Trace_Panel(app.Mainapp.ContStaticSpectrumWindow,app.Mainapp,"StaticEventSpectrum");
                    end
                end
            end
            
            if strcmp(app.Mainapp.PlotAppearance.MainWindow.Data.Plottype,"DataLinesGrid")
                if ~isempty(app.Mainapp.ChannelAxes)
                    for k = 1:numel(app.Mainapp.ChannelAxes)
                        if isvalid(app.Mainapp.ChannelAxes{k})  
                            delete(app.Mainapp.ChannelAxes{k});
                        end
                    end
                    app.Mainapp.ChannelAxes = [];
                end
                if ~isempty(app.Mainapp.ChannelGrid)
                    delete(app.Mainapp.ChannelGrid)
                end
                Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            end

            % static Con power spectrum
            if ~isempty(app.Mainapp.ContStaticSpectrumWindow)
                if isvalid(app.Mainapp.ContStaticSpectrumWindow)
                    app.Mainapp.ProbeViewProperties = Utility_Plot_Interactive_Probe_View(app.UIAxes,app.Mainapp.Data.Info.ChannelSpacing,str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel),str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows),str2double(app.Mainapp.Data.Info.ProbeInfo.HorOffset),str2double(app.Mainapp.Data.Info.ProbeInfo.VertOffset),app.Mainapp.Data.Info.ProbeInfo.OffSetRowsDistance,ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,AllActiveChannel,app.ShowChannelSpacingCheckBox.Value,0,1,[],app.Mainapp.Data.Info.ProbeInfo.OffSetRows,0,app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel,app.Mainapp.Data.Info.ProbeInfo.SwitchLeftRightChannel,app.Mainapp.Data.Info.ProbeInfo.ECoGArray);
                    if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,'Band Power over Depth')
                        [app.Mainapp.PowerSpecResults,app.Mainapp.ContStaticSpectrumWindow.BandPower,~] = Continous_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.Mainapp.ContStaticSpectrumWindow.DataSourceDropDown.Value,app.Mainapp.PowerSpecResults,app.Mainapp.ContStaticSpectrumWindow.BandPower,app.Mainapp.ContStaticSpectrumWindow.FrequencyRangeHzEditField.Value,app.Mainapp.ContStaticSpectrumWindow.UIAxes,app.Mainapp.ContStaticSpectrumWindow.UIAxes_2,app.Mainapp.ContStaticSpectrumWindow.TextArea,'All',app.Mainapp.ContStaticSpectrumWindow.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.Mainapp.PreservePlotChannelLocations);
                    else
                        [app.Mainapp.PowerSpecResults,app.Mainapp.ContStaticSpectrumWindow.BandPower,~] = Continous_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.Mainapp.ContStaticSpectrumWindow.DataSourceDropDown.Value,app.Mainapp.PowerSpecResults,app.Mainapp.ContStaticSpectrumWindow.BandPower,app.Mainapp.ContStaticSpectrumWindow.FrequencyRangeHzEditField.Value,app.Mainapp.ContStaticSpectrumWindow.UIAxes,app.Mainapp.ContStaticSpectrumWindow.UIAxes_2,app.Mainapp.ContStaticSpectrumWindow.TextArea,'Just Frequency Bands',app.Mainapp.ContStaticSpectrumWindow.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.Mainapp.PreservePlotChannelLocations);
                    end
                    if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,'Band Power over Depth')
                        cb = colorbar(app.Mainapp.ContStaticSpectrumWindow.UIAxes);
                        cb.Color = 'k';              % Sets tick mark and label color to black
                        cb.Label.String = "Power [dB]";
                        cb.Label.Rotation = 270;
                        %cb.FontSize =  app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeFontSize;
                        cb.Label.Color = 'k';        % Sets the color of the label text
                    else
                        cb = colorbar(app.Mainapp.ContStaticSpectrumWindow.UIAxes);   % Create a colorbar (if it exists)
                        if ~isempty(cb)
                            delete(cb);                  % Delete the colorbar
                        end
                    end
                    if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,'Band Power Across Grid')
                        Freqs = str2double(strsplit(app.Mainapp.ContStaticSpectrumWindow.FrequencyRangeHzEditField.Value,','));
                        [~,FreqsIndicie(1)] = min(abs(app.Mainapp.ContStaticSpectrumWindow.BandPower.F - Freqs(1)));
                        [~,FreqsIndicie(2)] = min(abs(app.Mainapp.ContStaticSpectrumWindow.BandPower.F - Freqs(2)));
        
                        EventRelatedSpectrum = app.Mainapp.ContStaticSpectrumWindow.BandPower.allPowerEst(:,FreqsIndicie(1):FreqsIndicie(2));
            
                        [Channelrange] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.Mainapp.ActiveChannel,'MainPlot');
                        EventRelatedSpectrum = EventRelatedSpectrum(Channelrange,:);
                        
                        Ylims = [min(EventRelatedSpectrum,[],'all') max(EventRelatedSpectrum,[],'all')];
        
                        % EventRelatedData is channel by time
                        Module_Main_Window_Plot_Grid_Trace_View(app.Mainapp.ContStaticSpectrumWindow,app.Mainapp.Data,EventRelatedSpectrum,app.Mainapp.ContStaticSpectrumWindow.BandPower.F(FreqsIndicie(1):FreqsIndicie(2))',0,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations,[],app.Mainapp.PlotAppearance.SpectrumWindow.GridPlotType,0,Ylims);
                    end


                end
            end
           
            % Con spikes
            if ~isempty(app.Mainapp.ConInternalSpikesWindow) || ~isempty(app.Mainapp.ConKilosortSpikesWindow)
                app.Mainapp.ProbeViewProperties = Utility_Plot_Interactive_Probe_View(app.UIAxes,app.Mainapp.Data.Info.ChannelSpacing,str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel),str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows),str2double(app.Mainapp.Data.Info.ProbeInfo.HorOffset),str2double(app.Mainapp.Data.Info.ProbeInfo.VertOffset),app.Mainapp.Data.Info.ProbeInfo.OffSetRowsDistance,app.Mainapp.ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,AllActiveChannel,app.ShowChannelSpacingCheckBox.Value,0,1,[],app.Mainapp.Data.Info.ProbeInfo.OffSetRows,0,app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel,app.Mainapp.Data.Info.ProbeInfo.SwitchLeftRightChannel,app.Mainapp.Data.Info.ProbeInfo.ECoGArray);
                [app] = Utility_ProbeChange_Plot_ContSpikes(app);
            end
          
            % Event spikes
            if ~isempty(app.Mainapp.EventInternalSpikesWindow) || ~isempty(app.Mainapp.EventKilosortSpikesWindow)
                app.Mainapp.ProbeViewProperties = Utility_Plot_Interactive_Probe_View(app.UIAxes,app.Mainapp.Data.Info.ChannelSpacing,str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel),str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows),str2double(app.Mainapp.Data.Info.ProbeInfo.HorOffset),str2double(app.Mainapp.Data.Info.ProbeInfo.VertOffset),app.Mainapp.Data.Info.ProbeInfo.OffSetRowsDistance,app.Mainapp.ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,AllActiveChannel,app.ShowChannelSpacingCheckBox.Value,0,1,[],app.Mainapp.Data.Info.ProbeInfo.OffSetRows,0,app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel,app.Mainapp.Data.Info.ProbeInfo.SwitchLeftRightChannel,app.Mainapp.Data.Info.ProbeInfo.ECoGArray);
                [app] = Utility_ProbeChange_Plot_EventSpikes(app);
            end
            
            % Event CSD
            if ~isempty(app.Mainapp.EventLFPCSD) 
                [app] = Utility_ProbeChange_Plot_EventRelatedLFP(app,'CSD');
            end
            % Event ERP
            if ~isempty(app.Mainapp.EventLFPERP) 
                [app] = Utility_ProbeChange_Plot_EventRelatedLFP(app,'ERP');
            end
            % Event Static spectrum
            if ~isempty(app.Mainapp.EventLFPSSP)
                [app] = Utility_ProbeChange_Plot_EventRelatedLFP(app,'EventSpectrum');
            end
        end

        % Menu selected function: JustLeftColumnMenu
        function JustLeftColumnMenuSelected(app, event)
            if ~isfield(app.Mainapp.Data,'Raw') && ~isfield(app.Mainapp.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end

            Window = app.ChangeforWindowDropDown.Value;

            NrChannel = length(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
            
            app.Mainapp.ActiveChannel = [];
            
            if str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows) == 1
                if ~app.Mainapp.Data.Info.ProbeInfo.OffSetRows
                    ChannelToTake = app.Mainapp.Data.Info.ProbeInfo.ActiveChannel<=str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel);
                else
                    ChannelToTake = 1:2:length(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
                end

                app.Mainapp.ActiveChannel = app.Mainapp.Data.Info.ProbeInfo.ActiveChannel(ChannelToTake);
            elseif str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows) == 2
                
                AllX = unique(app.Mainapp.Data.Info.ProbeInfo.xcoords);
                XAllActive = app.Mainapp.Data.Info.ProbeInfo.xcoords(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
                ChannelToTake = XAllActive == min(AllX);
                
                app.Mainapp.ActiveChannel = app.Mainapp.Data.Info.ProbeInfo.ActiveChannel(ChannelToTake);
            else % array stuff
                AllX = length(unique(app.Mainapp.Data.Info.ProbeInfo.xcoords(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel)));
                AllY = length(unique(app.Mainapp.Data.Info.ProbeInfo.ycoords(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel)));
                ChannelIndicieMatrix = reshape(1:(AllY*AllX), AllX, AllY)'; 
                
                TempActiveChannel = ChannelIndicieMatrix(:, 1);
                app.Mainapp.ActiveChannel = sort(TempActiveChannel(:));
                app.Mainapp.ActiveChannel(~ismember(app.Mainapp.ActiveChannel,app.Mainapp.Data.Info.ProbeInfo.ActiveChannel)) = [];
            end

            if strcmp(Window,'Main Window') || strcmp(Window,'All Windows Opened') || strcmp(Window,'Main Plot Current Source Density') || strcmp(Window,'Main Plot Power Estimate') || strcmp(Window,'Main Plot Spike Rate')
                %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                if strcmp(app.Mainapp.PlotAppearance.MainWindow.Data.Plottype,"DataLinesGrid")
                    if ~isempty(app.Mainapp.ChannelAxes)
                        for i = 1:length(app.Mainapp.ChannelAxes)
                            cla(app.Mainapp.ChannelAxes{i});
                        end
                    end
                end

                Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Subsequent","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
    
                if isscalar(app.Mainapp.ActiveChannel)
                    app.Mainapp.UIAxes.YTickMode = 'auto';
                    app.Mainapp.UIAxes.YTickLabelMode = 'auto';
                    ylabel(app.Mainapp.UIAxes,"Signal [mV]");
                else
                    app.Mainapp.UIAxes.YTick = [];         % Remove Y ticks
                    app.Mainapp.UIAxes.YTickLabel = {};    % Remove Y tick labels
                    ylabel(app.Mainapp.UIAxes,"");
                end
            end

            if strcmp(Window,"Static Spectrum Window") || strcmp(Window,"All Windows Opened")
                if ~isempty(app.Mainapp.ContStaticSpectrumWindow)
                    if isvalid(app.Mainapp.ContStaticSpectrumWindow)
                        if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,'Band Power over Depth')
                            [app.Mainapp.PowerSpecResults,app.Mainapp.ContStaticSpectrumWindow.BandPower,~] = Continous_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.Mainapp.ContStaticSpectrumWindow.DataSourceDropDown.Value,app.Mainapp.PowerSpecResults,app.Mainapp.ContStaticSpectrumWindow.BandPower,app.Mainapp.ContStaticSpectrumWindow.FrequencyRangeHzEditField.Value,app.Mainapp.ContStaticSpectrumWindow.UIAxes,app.Mainapp.ContStaticSpectrumWindow.UIAxes_2,app.Mainapp.ContStaticSpectrumWindow.TextArea,'All',app.Mainapp.ContStaticSpectrumWindow.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.Mainapp.PreservePlotChannelLocations);
                        else
                            if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,'Band Power Across Grid')
                                Freqs = str2double(strsplit(app.Mainapp.ContStaticSpectrumWindow.FrequencyRangeHzEditField.Value,','));
                                [~,FreqsIndicie(1)] = min(abs(app.Mainapp.ContStaticSpectrumWindow.BandPower.F - Freqs(1)));
                                [~,FreqsIndicie(2)] = min(abs(app.Mainapp.ContStaticSpectrumWindow.BandPower.F - Freqs(2)));
                
                                EventRelatedSpectrum = app.Mainapp.ContStaticSpectrumWindow.BandPower.allPowerEst(:,FreqsIndicie(1):FreqsIndicie(2));
                    
                                [Channelrange] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.Mainapp.ActiveChannel,'MainPlot');
                                EventRelatedSpectrum = EventRelatedSpectrum(Channelrange,:);
                                
                                Ylims = [min(EventRelatedSpectrum,[],'all') max(EventRelatedSpectrum,[],'all')];
                
                                % EventRelatedData is channel by time
                                Module_Main_Window_Plot_Grid_Trace_View(app.Mainapp.ContStaticSpectrumWindow,app.Mainapp.Data,EventRelatedSpectrum,app.Mainapp.ContStaticSpectrumWindow.BandPower.F(FreqsIndicie(1):FreqsIndicie(2))',0,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations,[],app.Mainapp.PlotAppearance.SpectrumWindow.GridPlotType,0,Ylims);
                            else
                                [app.Mainapp.PowerSpecResults,app.Mainapp.ContStaticSpectrumWindow.BandPower,~] = Continous_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.Mainapp.ContStaticSpectrumWindow.DataSourceDropDown.Value,app.Mainapp.PowerSpecResults,app.Mainapp.ContStaticSpectrumWindow.BandPower,app.Mainapp.ContStaticSpectrumWindow.FrequencyRangeHzEditField.Value,app.Mainapp.ContStaticSpectrumWindow.UIAxes,app.Mainapp.ContStaticSpectrumWindow.UIAxes_2,app.Mainapp.ContStaticSpectrumWindow.TextArea,'Just Frequency Bands',app.Mainapp.ContStaticSpectrumWindow.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.Mainapp.PreservePlotChannelLocations);
                            end
                        end
                    end
                end
            end

            if strcmp(Window,'Cont. Spike Analysis') || strcmp(Window,'All Windows Opened') 
                
                if ~isempty(app.Mainapp.ConInternalSpikesWindow) || ~isempty(app.Mainapp.ConKilosortSpikesWindow)
                    [app] = Utility_ProbeChange_Plot_ContSpikes(app);
                end
            end

            if strcmp(Window,'Event Spike Analysis') || strcmp(Window,'All Windows Opened') 
                if ~isempty(app.Mainapp.EventInternalSpikesWindow) || ~isempty(app.Mainapp.EventKilosortSpikesWindow)
                    [app] = Utility_ProbeChange_Plot_EventSpikes(app);
                end
            end

            if isfield(app.Mainapp.Data.Info.ProbeInfo,'CompleteAreaNames')
                BrainAreaInfo.CompleteAreaNames = app.Mainapp.Data.Info.ProbeInfo.CompleteAreaNames;
                BrainAreaInfo.ShortAreaNames = app.Mainapp.Data.Info.ProbeInfo.ShortAreaNames;
                BrainAreaInfo.AreaDistanceFromTip = app.Mainapp.Data.Info.ProbeInfo.AreaDistanceFromTip;
            else
                BrainAreaInfo = [];
            end

           if strcmp(Window,'Event ERP Window') || strcmp(Window,'All Windows Opened') || strcmp(Window,'Event CSD Window') || strcmp(Window,'Event CSD Window')|| strcmp(Window,'Event Static Spectrum Window')  || strcmp(Window,'Event Time Frequency Power Window') 
                if ~isempty(app.Mainapp.EventLFPERP) || ~isempty(app.Mainapp.EventLFPCSD) || ~isempty(app.Mainapp.EventLFPSSP) || ~isempty(app.Mainapp.EventLFPTF)
                    %app.Mainapp.ProbeViewProperties = Utility_Plot_Interactive_Probe_View(app.UIAxes,app.Mainapp.Data.Info.ChannelSpacing,str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel),str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows),str2double(app.Mainapp.Data.Info.ProbeInfo.HorOffset),str2double(app.Mainapp.Data.Info.ProbeInfo.VertOffset),app.Mainapp.Data.Info.ProbeInfo.OffSetRowsDistance,app.Mainapp.ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.ShowChannelSpacingCheckBox.Value);
                    if strcmp(Window,'Event CSD Window') && ~isempty(app.Mainapp.EventLFPCSD) || strcmp(Window,'All Windows Opened') & ~isempty(app.Mainapp.EventLFPCSD)
                        [app] = Utility_ProbeChange_Plot_EventRelatedLFP(app,'CSD');
                    end
                    if strcmp(Window,'Event ERP Window') && ~isempty(app.Mainapp.EventLFPERP) || strcmp(Window,'All Windows Opened') && ~isempty(app.Mainapp.EventLFPERP)
                        if strcmp(app.Mainapp.PlotAppearance.ERPWindow.PlotType,"GridTraceView")
                            if ~isempty(app.Mainapp.EventLFPERP.ChannelAxes)
                                for i = 1:length(app.Mainapp.EventLFPERP.ChannelAxes)
                                    cla(app.Mainapp.EventLFPERP.ChannelAxes{i});
                                end
                            end
                        end
                        [app] = Utility_ProbeChange_Plot_EventRelatedLFP(app,'ERP');
                    end
                    if strcmp(Window,'Event Static Spectrum Window') && ~isempty(app.Mainapp.EventLFPSSP) || strcmp(Window,'All Windows Opened') && ~isempty(app.Mainapp.EventLFPSSP)
                        [app] = Utility_ProbeChange_Plot_EventRelatedLFP(app,'EventSpectrum');
                    end
                    if strcmp(Window,'Event Time Frequency Power Window') && ~isempty(app.Mainapp.EventLFPTF) || strcmp(Window,'All Windows Opened') && ~isempty(app.Mainapp.EventLFPTF)
                        [app] = Utility_ProbeChange_Plot_EventRelatedLFP(app,'TF');
                    end
                end
           end

           if strcmp(Window,'Prepro Artefact Rejection') || strcmp(Window,'All Windows Opened')
                if ~isempty(app.Mainapp.PreproArtefactRejection)
                    [~,app.Mainapp.PreproArtefactRejection.StimArtefactInfo] = Preprocess_Extract_and_Plot_Stimulation_Artefact(app.Mainapp.Data, app.Mainapp.PreproArtefactRejection.TimeAroundEventsEditField_2.Value ,app.Mainapp.PreproArtefactRejection.TimeAroundEventsEditField.Value, app.Mainapp.PreproArtefactRejection.EventChannelforStimulationDropDown.Value, app.Mainapp.PreproArtefactRejection.EventstoPlotDropDown.Value, app.Mainapp.PreproArtefactRejection.Slider.Value, app.Mainapp.PreproArtefactRejection.UIAxes, app.Mainapp.ActiveChannel);            
                end
           end

            app.Mainapp.ProbeViewProperties = Utility_Plot_Interactive_Probe_View(app.UIAxes,app.Mainapp.Data.Info.ChannelSpacing,str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel),str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows),str2double(app.Mainapp.Data.Info.ProbeInfo.HorOffset),str2double(app.Mainapp.Data.Info.ProbeInfo.VertOffset),app.Mainapp.Data.Info.ProbeInfo.OffSetRowsDistance,app.Mainapp.ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.ShowChannelSpacingCheckBox.Value,1,1,[],app.Mainapp.Data.Info.ProbeInfo.OffSetRows,[],app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel,app.Mainapp.Data.Info.ProbeInfo.SwitchLeftRightChannel,app.Mainapp.Data.Info.ProbeInfo.ECoGArray);
        end

        % Menu selected function: JustRightColumnMenu
        function JustRightColumnMenuSelected(app, event)
            if ~isfield(app.Mainapp.Data,'Raw') && ~isfield(app.Mainapp.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end

            if str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows) == 1 && ~app.Mainapp.Data.Info.ProbeInfo.OffSetRows
                return;
            end

            Window = app.ChangeforWindowDropDown.Value;

            NrChannel = length(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
            
            app.Mainapp.ActiveChannel = [];
            
            if str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows) == 1
                if ~app.Mainapp.Data.Info.ProbeInfo.OffSetRows
                    ChannelToTake = app.Mainapp.Data.Info.ProbeInfo.ActiveChannel>str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel);
                else
                    ChannelToTake = 2:2:length(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
                end
                app.Mainapp.ActiveChannel = app.Mainapp.Data.Info.ProbeInfo.ActiveChannel(ChannelToTake);
            elseif str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows) == 2
                AllX = unique(app.Mainapp.Data.Info.ProbeInfo.xcoords);
                XAllActive = app.Mainapp.Data.Info.ProbeInfo.xcoords(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
                ChannelToTake = XAllActive == max(AllX);
                app.Mainapp.ActiveChannel = app.Mainapp.Data.Info.ProbeInfo.ActiveChannel(ChannelToTake);
            else % array stuff
                AllX = length(unique(app.Mainapp.Data.Info.ProbeInfo.xcoords(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel)));
                AllY = length(unique(app.Mainapp.Data.Info.ProbeInfo.ycoords(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel)));
                ChannelIndicieMatrix = reshape(1:(AllY*AllX), AllX, AllY)'; 
                
                TempActiveChannel = ChannelIndicieMatrix(:, end);
                app.Mainapp.ActiveChannel = sort(TempActiveChannel(:));
                app.Mainapp.ActiveChannel(~ismember(app.Mainapp.ActiveChannel,app.Mainapp.Data.Info.ProbeInfo.ActiveChannel)) = [];
            end

            if strcmp(Window,'Main Window') || strcmp(Window,'All Windows Opened') || strcmp(Window,'Main Plot Current Source Density') || strcmp(Window,'Main Plot Power Estimate') || strcmp(Window,'Main Plot Spike Rate')
                %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                if strcmp(app.Mainapp.PlotAppearance.MainWindow.Data.Plottype,"DataLinesGrid")
                    if ~isempty(app.Mainapp.ChannelAxes)
                        for i = 1:length(app.Mainapp.ChannelAxes)
                            cla(app.Mainapp.ChannelAxes{i});
                        end
                    end
                end

                Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Subsequent","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
    
                if isscalar(app.Mainapp.ActiveChannel)
                    app.Mainapp.UIAxes.YTickMode = 'auto';
                    app.Mainapp.UIAxes.YTickLabelMode = 'auto';
                    ylabel(app.Mainapp.UIAxes,"Signal [mV]");
                else
                    app.Mainapp.UIAxes.YTick = [];         % Remove Y ticks
                    app.Mainapp.UIAxes.YTickLabel = {};    % Remove Y tick labels
                    ylabel(app.Mainapp.UIAxes,"");
                end
            end

            if strcmp(Window,"Static Spectrum Window") || strcmp(Window,"All Windows Opened")
                if ~isempty(app.Mainapp.ContStaticSpectrumWindow)
                    if isvalid(app.Mainapp.ContStaticSpectrumWindow)
                        if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,'Band Power over Depth')
                            [app.Mainapp.PowerSpecResults,app.Mainapp.ContStaticSpectrumWindow.BandPower,~] = Continous_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.Mainapp.ContStaticSpectrumWindow.DataSourceDropDown.Value,app.Mainapp.PowerSpecResults,app.Mainapp.ContStaticSpectrumWindow.BandPower,app.Mainapp.ContStaticSpectrumWindow.FrequencyRangeHzEditField.Value,app.Mainapp.ContStaticSpectrumWindow.UIAxes,app.Mainapp.ContStaticSpectrumWindow.UIAxes_2,app.Mainapp.ContStaticSpectrumWindow.TextArea,'All',app.Mainapp.ContStaticSpectrumWindow.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.Mainapp.PreservePlotChannelLocations);
                        else
                            if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,'Band Power Across Grid')
                                Freqs = str2double(strsplit(app.Mainapp.ContStaticSpectrumWindow.FrequencyRangeHzEditField.Value,','));
                                [~,FreqsIndicie(1)] = min(abs(app.Mainapp.ContStaticSpectrumWindow.BandPower.F - Freqs(1)));
                                [~,FreqsIndicie(2)] = min(abs(app.Mainapp.ContStaticSpectrumWindow.BandPower.F - Freqs(2)));
                
                                EventRelatedSpectrum = app.Mainapp.ContStaticSpectrumWindow.BandPower.allPowerEst(:,FreqsIndicie(1):FreqsIndicie(2));
                    
                                [Channelrange] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.Mainapp.ActiveChannel,'MainPlot');
                                EventRelatedSpectrum = EventRelatedSpectrum(Channelrange,:);
                                
                                Ylims = [min(EventRelatedSpectrum,[],'all') max(EventRelatedSpectrum,[],'all')];
                
                                % EventRelatedData is channel by time
                                Module_Main_Window_Plot_Grid_Trace_View(app.Mainapp.ContStaticSpectrumWindow,app.Mainapp.Data,EventRelatedSpectrum,app.Mainapp.ContStaticSpectrumWindow.BandPower.F(FreqsIndicie(1):FreqsIndicie(2))',0,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations,[],app.Mainapp.PlotAppearance.SpectrumWindow.GridPlotType,0,Ylims);
                            else
                                [app.Mainapp.PowerSpecResults,app.Mainapp.ContStaticSpectrumWindow.BandPower,~] = Continous_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.Mainapp.ContStaticSpectrumWindow.DataSourceDropDown.Value,app.Mainapp.PowerSpecResults,app.Mainapp.ContStaticSpectrumWindow.BandPower,app.Mainapp.ContStaticSpectrumWindow.FrequencyRangeHzEditField.Value,app.Mainapp.ContStaticSpectrumWindow.UIAxes,app.Mainapp.ContStaticSpectrumWindow.UIAxes_2,app.Mainapp.ContStaticSpectrumWindow.TextArea,'Just Frequency Bands',app.Mainapp.ContStaticSpectrumWindow.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.Mainapp.PreservePlotChannelLocations);
                            end
                        end
                    end
                end
            end

            if strcmp(Window,'Cont. Spike Analysis') || strcmp(Window,'All Windows Opened') 
                
                if ~isempty(app.Mainapp.ConInternalSpikesWindow) || ~isempty(app.Mainapp.ConKilosortSpikesWindow)
                    [app] = Utility_ProbeChange_Plot_ContSpikes(app);
                end
            end

            if strcmp(Window,'Event Spike Analysis') || strcmp(Window,'All Windows Opened') 
                if ~isempty(app.Mainapp.EventInternalSpikesWindow) || ~isempty(app.Mainapp.EventKilosortSpikesWindow)
                    [app] = Utility_ProbeChange_Plot_EventSpikes(app);
                end
            end

            if isfield(app.Mainapp.Data.Info.ProbeInfo,'CompleteAreaNames')
                BrainAreaInfo.CompleteAreaNames = app.Mainapp.Data.Info.ProbeInfo.CompleteAreaNames;
                BrainAreaInfo.ShortAreaNames = app.Mainapp.Data.Info.ProbeInfo.ShortAreaNames;
                BrainAreaInfo.AreaDistanceFromTip = app.Mainapp.Data.Info.ProbeInfo.AreaDistanceFromTip;
            else
                BrainAreaInfo = [];
            end

           if strcmp(Window,'Event ERP Window') || strcmp(Window,'All Windows Opened') || strcmp(Window,'Event CSD Window') || strcmp(Window,'Event CSD Window')|| strcmp(Window,'Event Static Spectrum Window')  || strcmp(Window,'Event Time Frequency Power Window') 
                if ~isempty(app.Mainapp.EventLFPERP) || ~isempty(app.Mainapp.EventLFPCSD) || ~isempty(app.Mainapp.EventLFPSSP) || ~isempty(app.Mainapp.EventLFPTF)
                    %app.Mainapp.ProbeViewProperties = Utility_Plot_Interactive_Probe_View(app.UIAxes,app.Mainapp.Data.Info.ChannelSpacing,str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel),str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows),str2double(app.Mainapp.Data.Info.ProbeInfo.HorOffset),str2double(app.Mainapp.Data.Info.ProbeInfo.VertOffset),app.Mainapp.Data.Info.ProbeInfo.OffSetRowsDistance,app.Mainapp.ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.ShowChannelSpacingCheckBox.Value);
                    if strcmp(Window,'Event CSD Window') && ~isempty(app.Mainapp.EventLFPCSD) || strcmp(Window,'All Windows Opened') & ~isempty(app.Mainapp.EventLFPCSD)
                        [app] = Utility_ProbeChange_Plot_EventRelatedLFP(app,'CSD');
                    end
                    if strcmp(Window,'Event ERP Window') && ~isempty(app.Mainapp.EventLFPERP) || strcmp(Window,'All Windows Opened') && ~isempty(app.Mainapp.EventLFPERP)
                        if strcmp(app.Mainapp.PlotAppearance.ERPWindow.PlotType,"GridTraceView")
                            if ~isempty(app.Mainapp.EventLFPERP.ChannelAxes)
                                for i = 1:length(app.Mainapp.EventLFPERP.ChannelAxes)
                                    cla(app.Mainapp.EventLFPERP.ChannelAxes{i});
                                end
                            end
                        end
                        [app] = Utility_ProbeChange_Plot_EventRelatedLFP(app,'ERP');
                    end
                    if strcmp(Window,'Event Static Spectrum Window') && ~isempty(app.Mainapp.EventLFPSSP) || strcmp(Window,'All Windows Opened') && ~isempty(app.Mainapp.EventLFPSSP)
                        [app] = Utility_ProbeChange_Plot_EventRelatedLFP(app,'EventSpectrum');
                    end
                    if strcmp(Window,'Event Time Frequency Power Window') && ~isempty(app.Mainapp.EventLFPTF) || strcmp(Window,'All Windows Opened') && ~isempty(app.Mainapp.EventLFPTF)
                        [app] = Utility_ProbeChange_Plot_EventRelatedLFP(app,'TF');
                    end
                end
           end

           if strcmp(Window,'Prepro Artefact Rejection') || strcmp(Window,'All Windows Opened')
                if ~isempty(app.Mainapp.PreproArtefactRejection)
                    [~,app.Mainapp.PreproArtefactRejection.StimArtefactInfo] = Preprocess_Extract_and_Plot_Stimulation_Artefact(app.Mainapp.Data, app.Mainapp.PreproArtefactRejection.TimeAroundEventsEditField_2.Value ,app.Mainapp.PreproArtefactRejection.TimeAroundEventsEditField.Value, app.Mainapp.PreproArtefactRejection.EventChannelforStimulationDropDown.Value, app.Mainapp.PreproArtefactRejection.EventstoPlotDropDown.Value, app.Mainapp.PreproArtefactRejection.Slider.Value, app.Mainapp.PreproArtefactRejection.UIAxes, app.Mainapp.ActiveChannel);            
                end
           end

            app.Mainapp.ProbeViewProperties = Utility_Plot_Interactive_Probe_View(app.UIAxes,app.Mainapp.Data.Info.ChannelSpacing,str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel),str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows),str2double(app.Mainapp.Data.Info.ProbeInfo.HorOffset),str2double(app.Mainapp.Data.Info.ProbeInfo.VertOffset),app.Mainapp.Data.Info.ProbeInfo.OffSetRowsDistance,app.Mainapp.ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.ShowChannelSpacingCheckBox.Value,1,1,[],app.Mainapp.Data.Info.ProbeInfo.OffSetRows,[],app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel,app.Mainapp.Data.Info.ProbeInfo.SwitchLeftRightChannel,app.Mainapp.Data.Info.ProbeInfo.ECoGArray);
        end

        % Menu selected function: EveryChannelMenu
        function EveryChannelMenuSelected(app, event)
            if ~isfield(app.Mainapp.Data,'Raw') && ~isfield(app.Mainapp.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end

            Window = app.ChangeforWindowDropDown.Value;

            NrChannel = length(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
            
            app.Mainapp.ActiveChannel = [];
            
            app.Mainapp.ActiveChannel = app.Mainapp.Data.Info.ProbeInfo.ActiveChannel;
            
            if strcmp(Window,'Main Window') || strcmp(Window,'All Windows Opened') || strcmp(Window,'Main Plot Current Source Density') || strcmp(Window,'Main Plot Power Estimate') || strcmp(Window,'Main Plot Spike Rate')
                %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not

                if strcmp(app.Mainapp.PlotAppearance.MainWindow.Data.Plottype,"DataLinesGrid")
                    if ~isempty(app.Mainapp.ChannelAxes)
                        for i = 1:length(app.Mainapp.ChannelAxes)
                            cla(app.Mainapp.ChannelAxes{i});
                        end
                    end
                end

                Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Subsequent","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
    
                if isscalar(app.Mainapp.ActiveChannel)
                    app.Mainapp.UIAxes.YTickMode = 'auto';
                    app.Mainapp.UIAxes.YTickLabelMode = 'auto';
                    ylabel(app.Mainapp.UIAxes,"Signal [mV]");
                else
                    app.Mainapp.UIAxes.YTick = [];         % Remove Y ticks
                    app.Mainapp.UIAxes.YTickLabel = {};    % Remove Y tick labels
                    ylabel(app.Mainapp.UIAxes,"");
                end
            end

            if strcmp(Window,"Static Spectrum Window") || strcmp(Window,"All Windows Opened")
                if ~isempty(app.Mainapp.ContStaticSpectrumWindow)
                    if isvalid(app.Mainapp.ContStaticSpectrumWindow)
                        if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,'Band Power over Depth')
                            [app.Mainapp.PowerSpecResults,app.Mainapp.ContStaticSpectrumWindow.BandPower,~] = Continous_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.Mainapp.ContStaticSpectrumWindow.DataSourceDropDown.Value,app.Mainapp.PowerSpecResults,app.Mainapp.ContStaticSpectrumWindow.BandPower,app.Mainapp.ContStaticSpectrumWindow.FrequencyRangeHzEditField.Value,app.Mainapp.ContStaticSpectrumWindow.UIAxes,app.Mainapp.ContStaticSpectrumWindow.UIAxes_2,app.Mainapp.ContStaticSpectrumWindow.TextArea,'All',app.Mainapp.ContStaticSpectrumWindow.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.Mainapp.PreservePlotChannelLocations);
                        else
                            if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,'Band Power Across Grid')
                                Freqs = str2double(strsplit(app.Mainapp.ContStaticSpectrumWindow.FrequencyRangeHzEditField.Value,','));
                                [~,FreqsIndicie(1)] = min(abs(app.Mainapp.ContStaticSpectrumWindow.BandPower.F - Freqs(1)));
                                [~,FreqsIndicie(2)] = min(abs(app.Mainapp.ContStaticSpectrumWindow.BandPower.F - Freqs(2)));
                
                                EventRelatedSpectrum = app.Mainapp.ContStaticSpectrumWindow.BandPower.allPowerEst(:,FreqsIndicie(1):FreqsIndicie(2));
                    
                                [Channelrange] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.Mainapp.ActiveChannel,'MainPlot');
                                EventRelatedSpectrum = EventRelatedSpectrum(Channelrange,:);
                                
                                Ylims = [min(EventRelatedSpectrum,[],'all') max(EventRelatedSpectrum,[],'all')];
                
                                % EventRelatedData is channel by time
                                Module_Main_Window_Plot_Grid_Trace_View(app.Mainapp.ContStaticSpectrumWindow,app.Mainapp.Data,EventRelatedSpectrum,app.Mainapp.ContStaticSpectrumWindow.BandPower.F(FreqsIndicie(1):FreqsIndicie(2))',0,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations,[],app.Mainapp.PlotAppearance.SpectrumWindow.GridPlotType,0,Ylims);
                            else
                                [app.Mainapp.PowerSpecResults,app.Mainapp.ContStaticSpectrumWindow.BandPower,~] = Continous_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.Mainapp.ContStaticSpectrumWindow.DataSourceDropDown.Value,app.Mainapp.PowerSpecResults,app.Mainapp.ContStaticSpectrumWindow.BandPower,app.Mainapp.ContStaticSpectrumWindow.FrequencyRangeHzEditField.Value,app.Mainapp.ContStaticSpectrumWindow.UIAxes,app.Mainapp.ContStaticSpectrumWindow.UIAxes_2,app.Mainapp.ContStaticSpectrumWindow.TextArea,'Just Frequency Bands',app.Mainapp.ContStaticSpectrumWindow.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.Mainapp.PreservePlotChannelLocations);
                            end
                        end
                    end
                end
            end

            if strcmp(Window,'Cont. Spike Analysis') || strcmp(Window,'All Windows Opened') 
                
                if ~isempty(app.Mainapp.ConInternalSpikesWindow) || ~isempty(app.Mainapp.ConKilosortSpikesWindow)
                    [app] = Utility_ProbeChange_Plot_ContSpikes(app);
                end
            end

            if strcmp(Window,'Event Spike Analysis') || strcmp(Window,'All Windows Opened') 
                if ~isempty(app.Mainapp.EventInternalSpikesWindow) || ~isempty(app.Mainapp.EventKilosortSpikesWindow)
                    [app] = Utility_ProbeChange_Plot_EventSpikes(app);
                end
            end

            if isfield(app.Mainapp.Data.Info.ProbeInfo,'CompleteAreaNames')
                BrainAreaInfo.CompleteAreaNames = app.Mainapp.Data.Info.ProbeInfo.CompleteAreaNames;
                BrainAreaInfo.ShortAreaNames = app.Mainapp.Data.Info.ProbeInfo.ShortAreaNames;
                BrainAreaInfo.AreaDistanceFromTip = app.Mainapp.Data.Info.ProbeInfo.AreaDistanceFromTip;
            else
                BrainAreaInfo = [];
            end

           if strcmp(Window,'Event ERP Window') || strcmp(Window,'All Windows Opened') || strcmp(Window,'Event CSD Window') || strcmp(Window,'Event CSD Window')|| strcmp(Window,'Event Static Spectrum Window')  || strcmp(Window,'Event Time Frequency Power Window') 
                if ~isempty(app.Mainapp.EventLFPERP) || ~isempty(app.Mainapp.EventLFPCSD) || ~isempty(app.Mainapp.EventLFPSSP) || ~isempty(app.Mainapp.EventLFPTF)
                    %app.Mainapp.ProbeViewProperties = Utility_Plot_Interactive_Probe_View(app.UIAxes,app.Mainapp.Data.Info.ChannelSpacing,str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel),str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows),str2double(app.Mainapp.Data.Info.ProbeInfo.HorOffset),str2double(app.Mainapp.Data.Info.ProbeInfo.VertOffset),app.Mainapp.Data.Info.ProbeInfo.OffSetRowsDistance,app.Mainapp.ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.ShowChannelSpacingCheckBox.Value);
                    if strcmp(Window,'Event CSD Window') && ~isempty(app.Mainapp.EventLFPCSD) || strcmp(Window,'All Windows Opened') & ~isempty(app.Mainapp.EventLFPCSD)
                        [app] = Utility_ProbeChange_Plot_EventRelatedLFP(app,'CSD');
                    end
                    if strcmp(Window,'Event ERP Window') && ~isempty(app.Mainapp.EventLFPERP) || strcmp(Window,'All Windows Opened') && ~isempty(app.Mainapp.EventLFPERP)
                        if strcmp(app.Mainapp.PlotAppearance.ERPWindow.PlotType,"GridTraceView")
                            if ~isempty(app.Mainapp.EventLFPERP.ChannelAxes)
                                for i = 1:length(app.Mainapp.EventLFPERP.ChannelAxes)
                                    cla(app.Mainapp.EventLFPERP.ChannelAxes{i});
                                end
                            end
                        end
                        [app] = Utility_ProbeChange_Plot_EventRelatedLFP(app,'ERP');
                    end
                    if strcmp(Window,'Event Static Spectrum Window') && ~isempty(app.Mainapp.EventLFPSSP) || strcmp(Window,'All Windows Opened') && ~isempty(app.Mainapp.EventLFPSSP)
                        [app] = Utility_ProbeChange_Plot_EventRelatedLFP(app,'EventSpectrum');
                    end
                    if strcmp(Window,'Event Time Frequency Power Window') && ~isempty(app.Mainapp.EventLFPTF) || strcmp(Window,'All Windows Opened') && ~isempty(app.Mainapp.EventLFPTF)
                        [app] = Utility_ProbeChange_Plot_EventRelatedLFP(app,'TF');
                    end
                end
           end

           if strcmp(Window,'Prepro Artefact Rejection') || strcmp(Window,'All Windows Opened')
                if ~isempty(app.Mainapp.PreproArtefactRejection)
                    [~,app.Mainapp.PreproArtefactRejection.StimArtefactInfo] = Preprocess_Extract_and_Plot_Stimulation_Artefact(app.Mainapp.Data, app.Mainapp.PreproArtefactRejection.TimeAroundEventsEditField_2.Value ,app.Mainapp.PreproArtefactRejection.TimeAroundEventsEditField.Value, app.Mainapp.PreproArtefactRejection.EventChannelforStimulationDropDown.Value, app.Mainapp.PreproArtefactRejection.EventstoPlotDropDown.Value, app.Mainapp.PreproArtefactRejection.Slider.Value, app.Mainapp.PreproArtefactRejection.UIAxes, app.Mainapp.ActiveChannel);            
                end
           end

            app.Mainapp.ProbeViewProperties = Utility_Plot_Interactive_Probe_View(app.UIAxes,app.Mainapp.Data.Info.ChannelSpacing,str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel),str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows),str2double(app.Mainapp.Data.Info.ProbeInfo.HorOffset),str2double(app.Mainapp.Data.Info.ProbeInfo.VertOffset),app.Mainapp.Data.Info.ProbeInfo.OffSetRowsDistance,app.Mainapp.ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.ShowChannelSpacingCheckBox.Value,1,1,[],app.Mainapp.Data.Info.ProbeInfo.OffSetRows,[],app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel,app.Mainapp.Data.Info.ProbeInfo.SwitchLeftRightChannel,app.Mainapp.Data.Info.ProbeInfo.ECoGArray);
        end

        % Menu selected function: ShowHelpWindowMenu
        function ShowHelpWindowMenuSelected(app, event)
            Probe_Window_Help(app.Mainapp)
        end

        % Menu selected function: SelectSpecificColumnsMenu
        function SelectSpecificColumnsMenuSelected(app, event)
            h = Channel_Spacing_Selection(app.Mainapp,"ProbeViewRowSelection");
            waitfor(h);
            if isfield(app.Mainapp.Data.Info.ProbeInfo,'CompleteAreaNames')
                BrainAreaInfo.CompleteAreaNames = app.Mainapp.Data.Info.ProbeInfo.CompleteAreaNames;
                BrainAreaInfo.ShortAreaNames = app.Mainapp.Data.Info.ProbeInfo.ShortAreaNames;
                BrainAreaInfo.AreaDistanceFromTip = app.Mainapp.Data.Info.ProbeInfo.AreaDistanceFromTip;
            else
                BrainAreaInfo = [];
            end

            Window = app.ChangeforWindowDropDown.Value;

            if strcmp(Window,'Main Window') || strcmp(Window,'All Windows Opened') || strcmp(Window,'Main Plot Current Source Density') || strcmp(Window,'Main Plot Power Estimate') || strcmp(Window,'Main Plot Spike Rate') || strcmp(Window,'Main Plot Phase Synchronization')
          
                %app.Mainapp.ProbeViewProperties = Utility_Plot_Interactive_Probe_View(app.UIAxes,app.Mainapp.Data.Info.ChannelSpacing,str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel),str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows),str2double(app.Mainapp.Data.Info.ProbeInfo.HorOffset),str2double(app.Mainapp.Data.Info.ProbeInfo.VertOffset),app.Mainapp.Data.Info.ProbeInfo.OffSetRowsDistance,ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,AllActiveChannel,app.ShowChannelSpacingCheckBox.Value,0,1,ChannelClicked,app.Mainapp.Data.Info.ProbeInfo.OffSetRows,TwoRowOffsetDesignHit,app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel,app.Mainapp.Data.Info.ProbeInfo.SwitchLeftRightChannel)
                
                if strcmp(app.Mainapp.PlotAppearance.MainWindow.Data.Plottype,"DataLinesGrid")
                    if ~isempty(app.Mainapp.ChannelAxes)
                        for i = 1:length(app.Mainapp.ChannelAxes)
                            cla(app.Mainapp.ChannelAxes{i});
                        end
                    end
                end

                app.Mainapp.ChannelChange = "ProbeView";
    
                Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Subsequent","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
                
                if isscalar(app.Mainapp.ActiveChannel)
                    app.Mainapp.UIAxes.YTickMode = 'auto';
                    app.Mainapp.UIAxes.YTickLabelMode = 'auto';
                    ylabel(app.Mainapp.UIAxes,"Signal [mV]");
                else
                    app.Mainapp.UIAxes.YTick = [];         % Remove Y ticks
                    app.Mainapp.UIAxes.YTickLabel = {};    % Remove Y tick labels
                    ylabel(app.Mainapp.UIAxes,"");
                end
            end
           
            if strcmp(Window,'Static Spectrum Window') || strcmp(Window,'All Windows Opened')
                if ~isempty(app.Mainapp.ContStaticSpectrumWindow)
                    if isvalid(app.Mainapp.ContStaticSpectrumWindow)
                        %app.Mainapp.ProbeViewProperties = Utility_Plot_Interactive_Probe_View(app.UIAxes,app.Mainapp.Data.Info.ChannelSpacing,str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel),str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows),str2double(app.Mainapp.Data.Info.ProbeInfo.HorOffset),str2double(app.Mainapp.Data.Info.ProbeInfo.VertOffset),app.Mainapp.Data.Info.ProbeInfo.OffSetRowsDistance,ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,AllActiveChannel,app.ShowChannelSpacingCheckBox.Value,0,1,ChannelClicked,app.Mainapp.Data.Info.ProbeInfo.OffSetRows,TwoRowOffsetDesignHit,app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel,app.Mainapp.Data.Info.ProbeInfo.SwitchLeftRightChannel) 
                        if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,'Band Power over Depth')
                            [app.Mainapp.PowerSpecResults,app.Mainapp.ContStaticSpectrumWindow.BandPower,~] = Continous_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.Mainapp.ContStaticSpectrumWindow.DataSourceDropDown.Value,app.Mainapp.PowerSpecResults,app.Mainapp.ContStaticSpectrumWindow.BandPower,app.Mainapp.ContStaticSpectrumWindow.FrequencyRangeHzEditField.Value,app.Mainapp.ContStaticSpectrumWindow.UIAxes,app.Mainapp.ContStaticSpectrumWindow.UIAxes_2,app.Mainapp.ContStaticSpectrumWindow.TextArea,'All',app.Mainapp.ContStaticSpectrumWindow.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.Mainapp.PreservePlotChannelLocations);
                        else
                            [app.Mainapp.PowerSpecResults,app.Mainapp.ContStaticSpectrumWindow.BandPower,~] = Continous_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.Mainapp.ContStaticSpectrumWindow.DataSourceDropDown.Value,app.Mainapp.PowerSpecResults,app.Mainapp.ContStaticSpectrumWindow.BandPower,app.Mainapp.ContStaticSpectrumWindow.FrequencyRangeHzEditField.Value,app.Mainapp.ContStaticSpectrumWindow.UIAxes,app.Mainapp.ContStaticSpectrumWindow.UIAxes_2,app.Mainapp.ContStaticSpectrumWindow.TextArea,'Just Frequency Bands',app.Mainapp.ContStaticSpectrumWindow.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.Mainapp.PreservePlotChannelLocations);
                        end
                        if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,'Band Power over Depth')
                            cb = colorbar(app.Mainapp.ContStaticSpectrumWindow.UIAxes);
                            cb.Color = 'k';              % Sets tick mark and label color to black
                            cb.Label.String = "Power [dB]";
                            cb.Label.Rotation = 270;
                            %cb.FontSize =  app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeFontSize;
                            cb.Label.Color = 'k';        % Sets the color of the label text
                        else
                            cb = colorbar(app.Mainapp.ContStaticSpectrumWindow.UIAxes);   % Create a colorbar (if it exists)
                            if ~isempty(cb)
                                delete(cb);                  % Delete the colorbar
                            end
                        end
                        if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,'Band Power Across Grid')
                            Freqs = str2double(strsplit(app.Mainapp.ContStaticSpectrumWindow.FrequencyRangeHzEditField.Value,','));
                            [~,FreqsIndicie(1)] = min(abs(app.Mainapp.ContStaticSpectrumWindow.BandPower.F - Freqs(1)));
                            [~,FreqsIndicie(2)] = min(abs(app.Mainapp.ContStaticSpectrumWindow.BandPower.F - Freqs(2)));
            
                            EventRelatedSpectrum = app.Mainapp.ContStaticSpectrumWindow.BandPower.allPowerEst(:,FreqsIndicie(1):FreqsIndicie(2));
                
                            [Channelrange] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.Mainapp.ActiveChannel,'MainPlot');
                            EventRelatedSpectrum = EventRelatedSpectrum(Channelrange,:);
                            
                            Ylims = [min(EventRelatedSpectrum,[],'all') max(EventRelatedSpectrum,[],'all')];
            
                            % EventRelatedData is channel by time
                            Module_Main_Window_Plot_Grid_Trace_View(app.Mainapp.ContStaticSpectrumWindow,app.Mainapp.Data,EventRelatedSpectrum,app.Mainapp.ContStaticSpectrumWindow.BandPower.F(FreqsIndicie(1):FreqsIndicie(2))',0,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations,[],app.Mainapp.PlotAppearance.SpectrumWindow.GridPlotType,0,Ylims);
                        end

                    end
                end
            end
            
            if strcmp(Window,'All Windows Opened') || strcmp(Window,'Cont. Spike Analysis')
                if ~isempty(app.Mainapp.ConInternalSpikesWindow) || ~isempty(app.Mainapp.ConKilosortSpikesWindow)
                    %app.Mainapp.ProbeViewProperties = Utility_Plot_Interactive_Probe_View(app.UIAxes,app.Mainapp.Data.Info.ChannelSpacing,str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel),str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows),str2double(app.Mainapp.Data.Info.ProbeInfo.HorOffset),str2double(app.Mainapp.Data.Info.ProbeInfo.VertOffset),app.Mainapp.Data.Info.ProbeInfo.OffSetRowsDistance,ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,AllActiveChannel,app.ShowChannelSpacingCheckBox.Value,0,1,ChannelClicked,app.Mainapp.Data.Info.ProbeInfo.OffSetRows,TwoRowOffsetDesignHit,app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel,app.Mainapp.Data.Info.ProbeInfo.SwitchLeftRightChannel);
                    [app] = Utility_ProbeChange_Plot_ContSpikes(app);
                end
            end

            if strcmp(Window,'All Windows Opened') || strcmp(Window,'Event Spike Analysis')
                if ~isempty(app.Mainapp.EventInternalSpikesWindow) || ~isempty(app.Mainapp.EventKilosortSpikesWindow)
                    %app.Mainapp.ProbeViewProperties = Utility_Plot_Interactive_Probe_View(app.UIAxes,app.Mainapp.Data.Info.ChannelSpacing,str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel),str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows),str2double(app.Mainapp.Data.Info.ProbeInfo.HorOffset),str2double(app.Mainapp.Data.Info.ProbeInfo.VertOffset),app.Mainapp.Data.Info.ProbeInfo.OffSetRowsDistance,ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,AllActiveChannel,app.ShowChannelSpacingCheckBox.Value,0,1,ChannelClicked,app.Mainapp.Data.Info.ProbeInfo.OffSetRows,TwoRowOffsetDesignHit,app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel,app.Mainapp.Data.Info.ProbeInfo.SwitchLeftRightChannel);
                    [app] = Utility_ProbeChange_Plot_EventSpikes(app);
                end
            end
            
            if strcmp(Window,'All Windows Opened') || strcmp(Window,'Delete Event Trigger Window')
                if isprop(app.Mainapp,'EventIndiceRejectionWindow')
                    if ~isempty(app.Mainapp.EventIndiceRejectionWindow) && isvalid(app.Mainapp.EventIndiceRejectionWindow)
                        %app.Mainapp.ProbeViewProperties = Utility_Plot_Interactive_Probe_View(app.UIAxes,app.Mainapp.Data.Info.ChannelSpacing,str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel),str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows),str2double(app.Mainapp.Data.Info.ProbeInfo.HorOffset),str2double(app.Mainapp.Data.Info.ProbeInfo.VertOffset),app.Mainapp.Data.Info.ProbeInfo.OffSetRowsDistance,app.Mainapp.ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.ShowChannelSpacingCheckBox.Value,1,1,app.Mainapp.ActiveChannel,app.Mainapp.Data.Info.ProbeInfo.OffSetRows,[],app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel,app.Mainapp.Data.Info.ProbeInfo.SwitchLeftRightChannel,app.Mainapp.Data.Info.ProbeInfo.ECoGArray)
                        %% Extract and plot stim artefacts
                        if ~strcmp(app.Mainapp.EventIndiceRejectionWindow.EventstoPlotDropDown.Value,"Mean over all Trigger")
                            EventToPlot = str2double(app.Mainapp.EventIndiceRejectionWindow.EventstoPlotDropDown.Value);
                        else
                            EventToPlot = 1:length(app.Mainapp.Data.Events{1});
                        end
            
                        Organize_Plot_Trigger_Indices(app.Mainapp.Data,app.Mainapp.EventIndiceRejectionWindow.UIAxes,EventToPlot,app.Mainapp.Data.Info.EventRelatedTime,app.Mainapp.ActiveChannel,app.Mainapp.EventIndiceRejectionWindow.EventstoPlotDropDown.Value,app.Mainapp.EventIndiceRejectionWindow.Slider.Value,app.Mainapp.EventIndiceRejectionWindow.EventChannelforStimulationDropDown.Value,app.Mainapp.EventIndiceRejectionWindow.TimearoundEvent,app.Mainapp.EventIndiceRejectionWindow.DataToExtractFromDropDown.Value,"Raw Event Related Data",app.Mainapp.EventIndiceRejectionWindow.ColorMap);
                    end
                end
            end

            if strcmp(Window,'Event CSD Window') && ~isempty(app.Mainapp.EventLFPCSD) || strcmp(Window,'All Windows Opened') && ~isempty(app.Mainapp.EventLFPCSD)
                [app] = Utility_ProbeChange_Plot_EventRelatedLFP(app,'CSD');
            end
            if strcmp(Window,'Event ERP Window') && ~isempty(app.Mainapp.EventLFPERP) || strcmp(Window,'All Windows Opened')  && ~isempty(app.Mainapp.EventLFPERP)
                if strcmp(app.Mainapp.PlotAppearance.ERPWindow.PlotType,"GridTraceView")
                    if ~isempty(app.Mainapp.EventLFPERP.ChannelAxes)
                        for i = 1:length(app.Mainapp.EventLFPERP.ChannelAxes)
                            cla(app.Mainapp.EventLFPERP.ChannelAxes{i});
                        end
                    end
                end
                [app] = Utility_ProbeChange_Plot_EventRelatedLFP(app,'ERP');
            end
            if strcmp(Window,'Event Static Spectrum Window') && ~isempty(app.Mainapp.EventLFPSSP) || strcmp(Window,'All Windows Opened') && ~isempty(app.Mainapp.EventLFPSSP)
                [app] = Utility_ProbeChange_Plot_EventRelatedLFP(app,'EventSpectrum');
            end
            if strcmp(Window,'Event Time Frequency Power Window') && ~isempty(app.Mainapp.EventLFPTF) || strcmp(Window,'All Windows Opened') && ~isempty(app.Mainapp.EventLFPTF)
                [app] = Utility_ProbeChange_Plot_EventRelatedLFP(app,'TF');
            end
            if strcmp(Window,'Event Phase Synchronization') && ~isempty(app.Mainapp.EventPhaseSynchro) || strcmp(Window,'All Windows Opened') && ~isempty(app.Mainapp.EventPhaseSynchro)
                [app] = Utility_ProbeChange_Plot_EventRelatedLFP(app,'PhaseSync');
            end

            if strcmp(Window,'Prepro Artefact Rejection') || strcmp(Window,'All Windows Opened')
                if ~isempty(app.Mainapp.PreproArtefactRejection)
                    [~,app.Mainapp.PreproArtefactRejection.StimArtefactInfo] = Preprocess_Extract_and_Plot_Stimulation_Artefact(app.Mainapp.Data, app.Mainapp.PreproArtefactRejection.TimeAroundEventsEditField_2.Value ,app.Mainapp.PreproArtefactRejection.TimeAroundEventsEditField.Value, app.Mainapp.PreproArtefactRejection.EventChannelforStimulationDropDown.Value, app.Mainapp.PreproArtefactRejection.EventstoPlotDropDown.Value, app.Mainapp.PreproArtefactRejection.Slider.Value, app.Mainapp.PreproArtefactRejection.UIAxes, app.Mainapp.ActiveChannel);            
                end
            end
            
            app.Mainapp.ProbeViewProperties = Utility_Plot_Interactive_Probe_View(app.UIAxes,app.Mainapp.Data.Info.ChannelSpacing,str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel),str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows),str2double(app.Mainapp.Data.Info.ProbeInfo.HorOffset),str2double(app.Mainapp.Data.Info.ProbeInfo.VertOffset),app.Mainapp.Data.Info.ProbeInfo.OffSetRowsDistance,app.Mainapp.ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.ShowChannelSpacingCheckBox.Value,1,1,app.Mainapp.ActiveChannel,app.Mainapp.Data.Info.ProbeInfo.OffSetRows,[],app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel,app.Mainapp.Data.Info.ProbeInfo.SwitchLeftRightChannel,app.Mainapp.Data.Info.ProbeInfo.ECoGArray);
                
        end

        % Menu selected function: SelectSpecificRowsMenu_2
        function SelectSpecificRowsMenu_2Selected(app, event)
            h = Channel_Spacing_Selection(app.Mainapp,"ProbeViewColumnSelection");
            waitfor(h);

            if isfield(app.Mainapp.Data.Info.ProbeInfo,'CompleteAreaNames')
                BrainAreaInfo.CompleteAreaNames = app.Mainapp.Data.Info.ProbeInfo.CompleteAreaNames;
                BrainAreaInfo.ShortAreaNames = app.Mainapp.Data.Info.ProbeInfo.ShortAreaNames;
                BrainAreaInfo.AreaDistanceFromTip = app.Mainapp.Data.Info.ProbeInfo.AreaDistanceFromTip;
            else
                BrainAreaInfo = [];
            end
            
            Window = app.ChangeforWindowDropDown.Value;

            if strcmp(Window,'Main Window') || strcmp(Window,'All Windows Opened') || strcmp(Window,'Main Plot Current Source Density') || strcmp(Window,'Main Plot Power Estimate') || strcmp(Window,'Main Plot Spike Rate') || strcmp(Window,'Main Plot Phase Synchronization')
          
                %app.Mainapp.ProbeViewProperties = Utility_Plot_Interactive_Probe_View(app.UIAxes,app.Mainapp.Data.Info.ChannelSpacing,str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel),str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows),str2double(app.Mainapp.Data.Info.ProbeInfo.HorOffset),str2double(app.Mainapp.Data.Info.ProbeInfo.VertOffset),app.Mainapp.Data.Info.ProbeInfo.OffSetRowsDistance,ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,AllActiveChannel,app.ShowChannelSpacingCheckBox.Value,0,1,ChannelClicked,app.Mainapp.Data.Info.ProbeInfo.OffSetRows,TwoRowOffsetDesignHit,app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel,app.Mainapp.Data.Info.ProbeInfo.SwitchLeftRightChannel)
                
                if strcmp(app.Mainapp.PlotAppearance.MainWindow.Data.Plottype,"DataLinesGrid")
                    if ~isempty(app.Mainapp.ChannelAxes)
                        for i = 1:length(app.Mainapp.ChannelAxes)
                            cla(app.Mainapp.ChannelAxes{i});
                        end
                    end
                end

                app.Mainapp.ChannelChange = "ProbeView";
    
                Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Subsequent","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
                
                if isscalar(app.Mainapp.ActiveChannel)
                    app.Mainapp.UIAxes.YTickMode = 'auto';
                    app.Mainapp.UIAxes.YTickLabelMode = 'auto';
                    ylabel(app.Mainapp.UIAxes,"Signal [mV]");
                else
                    app.Mainapp.UIAxes.YTick = [];         % Remove Y ticks
                    app.Mainapp.UIAxes.YTickLabel = {};    % Remove Y tick labels
                    ylabel(app.Mainapp.UIAxes,"");
                end
            end
           
            if strcmp(Window,'Static Spectrum Window') || strcmp(Window,'All Windows Opened')
                if ~isempty(app.Mainapp.ContStaticSpectrumWindow)
                    if isvalid(app.Mainapp.ContStaticSpectrumWindow)
                        %app.Mainapp.ProbeViewProperties = Utility_Plot_Interactive_Probe_View(app.UIAxes,app.Mainapp.Data.Info.ChannelSpacing,str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel),str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows),str2double(app.Mainapp.Data.Info.ProbeInfo.HorOffset),str2double(app.Mainapp.Data.Info.ProbeInfo.VertOffset),app.Mainapp.Data.Info.ProbeInfo.OffSetRowsDistance,ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,AllActiveChannel,app.ShowChannelSpacingCheckBox.Value,0,1,ChannelClicked,app.Mainapp.Data.Info.ProbeInfo.OffSetRows,TwoRowOffsetDesignHit,app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel,app.Mainapp.Data.Info.ProbeInfo.SwitchLeftRightChannel) 
                        if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,'Band Power over Depth')
                            [app.Mainapp.PowerSpecResults,app.Mainapp.ContStaticSpectrumWindow.BandPower,~] = Continous_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.Mainapp.ContStaticSpectrumWindow.DataSourceDropDown.Value,app.Mainapp.PowerSpecResults,app.Mainapp.ContStaticSpectrumWindow.BandPower,app.Mainapp.ContStaticSpectrumWindow.FrequencyRangeHzEditField.Value,app.Mainapp.ContStaticSpectrumWindow.UIAxes,app.Mainapp.ContStaticSpectrumWindow.UIAxes_2,app.Mainapp.ContStaticSpectrumWindow.TextArea,'All',app.Mainapp.ContStaticSpectrumWindow.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.Mainapp.PreservePlotChannelLocations);
                        else
                            [app.Mainapp.PowerSpecResults,app.Mainapp.ContStaticSpectrumWindow.BandPower,~] = Continous_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.Mainapp.ContStaticSpectrumWindow.DataSourceDropDown.Value,app.Mainapp.PowerSpecResults,app.Mainapp.ContStaticSpectrumWindow.BandPower,app.Mainapp.ContStaticSpectrumWindow.FrequencyRangeHzEditField.Value,app.Mainapp.ContStaticSpectrumWindow.UIAxes,app.Mainapp.ContStaticSpectrumWindow.UIAxes_2,app.Mainapp.ContStaticSpectrumWindow.TextArea,'Just Frequency Bands',app.Mainapp.ContStaticSpectrumWindow.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.Mainapp.PreservePlotChannelLocations);
                        end
                        if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,'Band Power over Depth')
                            cb = colorbar(app.Mainapp.ContStaticSpectrumWindow.UIAxes);
                            cb.Color = 'k';              % Sets tick mark and label color to black
                            cb.Label.String = "Power [dB]";
                            cb.Label.Rotation = 270;
                            %cb.FontSize =  app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeFontSize;
                            cb.Label.Color = 'k';        % Sets the color of the label text
                        else
                            cb = colorbar(app.Mainapp.ContStaticSpectrumWindow.UIAxes);   % Create a colorbar (if it exists)
                            if ~isempty(cb)
                                delete(cb);                  % Delete the colorbar
                            end
                        end
                        if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,'Band Power Across Grid')
                            Freqs = str2double(strsplit(app.Mainapp.ContStaticSpectrumWindow.FrequencyRangeHzEditField.Value,','));
                            [~,FreqsIndicie(1)] = min(abs(app.Mainapp.ContStaticSpectrumWindow.BandPower.F - Freqs(1)));
                            [~,FreqsIndicie(2)] = min(abs(app.Mainapp.ContStaticSpectrumWindow.BandPower.F - Freqs(2)));
            
                            EventRelatedSpectrum = app.Mainapp.ContStaticSpectrumWindow.BandPower.allPowerEst(:,FreqsIndicie(1):FreqsIndicie(2));
                
                            [Channelrange] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.Mainapp.ActiveChannel,'MainPlot');
                            EventRelatedSpectrum = EventRelatedSpectrum(Channelrange,:);
                            
                            Ylims = [min(EventRelatedSpectrum,[],'all') max(EventRelatedSpectrum,[],'all')];
            
                            % EventRelatedData is channel by time
                            Module_Main_Window_Plot_Grid_Trace_View(app.Mainapp.ContStaticSpectrumWindow,app.Mainapp.Data,EventRelatedSpectrum,app.Mainapp.ContStaticSpectrumWindow.BandPower.F(FreqsIndicie(1):FreqsIndicie(2))',0,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations,[],app.Mainapp.PlotAppearance.SpectrumWindow.GridPlotType,0,Ylims);
                        end

                    end
                end
            end
            
            if strcmp(Window,'All Windows Opened') || strcmp(Window,'Cont. Spike Analysis')
                if ~isempty(app.Mainapp.ConInternalSpikesWindow) || ~isempty(app.Mainapp.ConKilosortSpikesWindow)
                    %app.Mainapp.ProbeViewProperties = Utility_Plot_Interactive_Probe_View(app.UIAxes,app.Mainapp.Data.Info.ChannelSpacing,str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel),str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows),str2double(app.Mainapp.Data.Info.ProbeInfo.HorOffset),str2double(app.Mainapp.Data.Info.ProbeInfo.VertOffset),app.Mainapp.Data.Info.ProbeInfo.OffSetRowsDistance,ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,AllActiveChannel,app.ShowChannelSpacingCheckBox.Value,0,1,ChannelClicked,app.Mainapp.Data.Info.ProbeInfo.OffSetRows,TwoRowOffsetDesignHit,app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel,app.Mainapp.Data.Info.ProbeInfo.SwitchLeftRightChannel);
                    [app] = Utility_ProbeChange_Plot_ContSpikes(app);
                end
            end

            if strcmp(Window,'All Windows Opened') || strcmp(Window,'Event Spike Analysis')
                if ~isempty(app.Mainapp.EventInternalSpikesWindow) || ~isempty(app.Mainapp.EventKilosortSpikesWindow)
                    %app.Mainapp.ProbeViewProperties = Utility_Plot_Interactive_Probe_View(app.UIAxes,app.Mainapp.Data.Info.ChannelSpacing,str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel),str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows),str2double(app.Mainapp.Data.Info.ProbeInfo.HorOffset),str2double(app.Mainapp.Data.Info.ProbeInfo.VertOffset),app.Mainapp.Data.Info.ProbeInfo.OffSetRowsDistance,ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,AllActiveChannel,app.ShowChannelSpacingCheckBox.Value,0,1,ChannelClicked,app.Mainapp.Data.Info.ProbeInfo.OffSetRows,TwoRowOffsetDesignHit,app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel,app.Mainapp.Data.Info.ProbeInfo.SwitchLeftRightChannel);
                    [app] = Utility_ProbeChange_Plot_EventSpikes(app);
                end
            end
            
            if strcmp(Window,'All Windows Opened') || strcmp(Window,'Delete Event Trigger Window')
                if isprop(app.Mainapp,'EventIndiceRejectionWindow')
                    if ~isempty(app.Mainapp.EventIndiceRejectionWindow) && isvalid(app.Mainapp.EventIndiceRejectionWindow)
                        %app.Mainapp.ProbeViewProperties = Utility_Plot_Interactive_Probe_View(app.UIAxes,app.Mainapp.Data.Info.ChannelSpacing,str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel),str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows),str2double(app.Mainapp.Data.Info.ProbeInfo.HorOffset),str2double(app.Mainapp.Data.Info.ProbeInfo.VertOffset),app.Mainapp.Data.Info.ProbeInfo.OffSetRowsDistance,app.Mainapp.ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.ShowChannelSpacingCheckBox.Value,1,1,app.Mainapp.ActiveChannel,app.Mainapp.Data.Info.ProbeInfo.OffSetRows,[],app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel,app.Mainapp.Data.Info.ProbeInfo.SwitchLeftRightChannel,app.Mainapp.Data.Info.ProbeInfo.ECoGArray)
                        %% Extract and plot stim artefacts
                        if ~strcmp(app.Mainapp.EventIndiceRejectionWindow.EventstoPlotDropDown.Value,"Mean over all Trigger")
                            EventToPlot = str2double(app.Mainapp.EventIndiceRejectionWindow.EventstoPlotDropDown.Value);
                        else
                            EventToPlot = 1:length(app.Mainapp.Data.Events{1});
                        end
            
                        Organize_Plot_Trigger_Indices(app.Mainapp.Data,app.Mainapp.EventIndiceRejectionWindow.UIAxes,EventToPlot,app.Mainapp.Data.Info.EventRelatedTime,app.Mainapp.ActiveChannel,app.Mainapp.EventIndiceRejectionWindow.EventstoPlotDropDown.Value,app.Mainapp.EventIndiceRejectionWindow.Slider.Value,app.Mainapp.EventIndiceRejectionWindow.EventChannelforStimulationDropDown.Value,app.Mainapp.EventIndiceRejectionWindow.TimearoundEvent,app.Mainapp.EventIndiceRejectionWindow.DataToExtractFromDropDown.Value,"Raw Event Related Data",app.Mainapp.EventIndiceRejectionWindow.ColorMap);
                    end
                end
            end

            if strcmp(Window,'Event CSD Window') && ~isempty(app.Mainapp.EventLFPCSD) || strcmp(Window,'All Windows Opened') && ~isempty(app.Mainapp.EventLFPCSD)
                [app] = Utility_ProbeChange_Plot_EventRelatedLFP(app,'CSD');
            end
            if strcmp(Window,'Event ERP Window') && ~isempty(app.Mainapp.EventLFPERP) || strcmp(Window,'All Windows Opened')  && ~isempty(app.Mainapp.EventLFPERP)
                if strcmp(app.Mainapp.PlotAppearance.ERPWindow.PlotType,"GridTraceView")
                    if ~isempty(app.Mainapp.EventLFPERP.ChannelAxes)
                        for i = 1:length(app.Mainapp.EventLFPERP.ChannelAxes)
                            cla(app.Mainapp.EventLFPERP.ChannelAxes{i});
                        end
                    end
                end
                [app] = Utility_ProbeChange_Plot_EventRelatedLFP(app,'ERP');
            end
            if strcmp(Window,'Event Static Spectrum Window') && ~isempty(app.Mainapp.EventLFPSSP) || strcmp(Window,'All Windows Opened') && ~isempty(app.Mainapp.EventLFPSSP)
                [app] = Utility_ProbeChange_Plot_EventRelatedLFP(app,'EventSpectrum');
            end
            if strcmp(Window,'Event Time Frequency Power Window') && ~isempty(app.Mainapp.EventLFPTF) || strcmp(Window,'All Windows Opened') && ~isempty(app.Mainapp.EventLFPTF)
                [app] = Utility_ProbeChange_Plot_EventRelatedLFP(app,'TF');
            end
            if strcmp(Window,'Event Phase Synchronization') && ~isempty(app.Mainapp.EventPhaseSynchro) || strcmp(Window,'All Windows Opened') && ~isempty(app.Mainapp.EventPhaseSynchro)
                [app] = Utility_ProbeChange_Plot_EventRelatedLFP(app,'PhaseSync');
            end

            if strcmp(Window,'Prepro Artefact Rejection') || strcmp(Window,'All Windows Opened')
                if ~isempty(app.Mainapp.PreproArtefactRejection)
                    [~,app.Mainapp.PreproArtefactRejection.StimArtefactInfo] = Preprocess_Extract_and_Plot_Stimulation_Artefact(app.Mainapp.Data, app.Mainapp.PreproArtefactRejection.TimeAroundEventsEditField_2.Value ,app.Mainapp.PreproArtefactRejection.TimeAroundEventsEditField.Value, app.Mainapp.PreproArtefactRejection.EventChannelforStimulationDropDown.Value, app.Mainapp.PreproArtefactRejection.EventstoPlotDropDown.Value, app.Mainapp.PreproArtefactRejection.Slider.Value, app.Mainapp.PreproArtefactRejection.UIAxes, app.Mainapp.ActiveChannel);            
                end
            end
            
            app.Mainapp.ProbeViewProperties = Utility_Plot_Interactive_Probe_View(app.UIAxes,app.Mainapp.Data.Info.ChannelSpacing,str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel),str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows),str2double(app.Mainapp.Data.Info.ProbeInfo.HorOffset),str2double(app.Mainapp.Data.Info.ProbeInfo.VertOffset),app.Mainapp.Data.Info.ProbeInfo.OffSetRowsDistance,app.Mainapp.ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.ShowChannelSpacingCheckBox.Value,1,1,app.Mainapp.ActiveChannel,app.Mainapp.Data.Info.ProbeInfo.OffSetRows,[],app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel,app.Mainapp.Data.Info.ProbeInfo.SwitchLeftRightChannel,app.Mainapp.Data.Info.ProbeInfo.ECoGArray);
                
        end

        % Menu selected function: ShowSpikePositionsonProbeMenu
        function ShowSpikePositionsonProbeMenuSelected(app, event)
            if ~isfield(app.Mainapp.Data,'Spikes')
                msgbox("No spike data found!");
                return;
            else
               if isempty(app.Mainapp.Data.Spikes)
                  msgbox("No spike data found!");
                  return;
               end
            end

            SpikeHandles = findobj(app.UIAxes, 'Tag', 'SpikePositions');
            if ~isempty(SpikeHandles)
                delete(SpikeHandles)
            end
            % overlay spike pos over probe
            if isfield(app.Mainapp.Data,'Spikes')
               ProbeView_Show_Spike_Positions(app.Mainapp.Data,app.Mainapp.ProbeViewProperties,app.UIAxes,app.SpikeCircleSizeMethod)
            else
                SpikeHandles = findobj(app.UIAxes, 'Tag', 'SpikePositions');
                if ~isempty(SpikeHandles)
                    delete(SpikeHandles)
                end
            end
        end

        % Menu selected function: HideSpikePositionsonProbeMenu
        function HideSpikePositionsonProbeMenuSelected(app, event)
            if ~isfield(app.Mainapp.Data,'Spikes')
                msgbox("No spike data found!");
                return;
            else
               if isempty(app.Mainapp.Data.Spikes)
                  msgbox("No spike data found!");
                  return;
               end
            end

            SpikeHandles = findobj(app.UIAxes, 'Tag', 'SpikePositions');
            if ~isempty(SpikeHandles)
                delete(SpikeHandles)
            end
        end

        % Menu selected function: SizeByAmplitudeMenu
        function SizeByAmplitudeMenuSelected(app, event)
            if ~isfield(app.Mainapp.Data,'Spikes')
                msgbox("No spike data found!");
                return;
            else
               if isempty(app.Mainapp.Data.Spikes)
                  msgbox("No spike data found!");
                  return;
               end
            end

            app.SpikeCircleSizeMethod = "SpikeAmps";

            % overlay spike pos over probe
            if isfield(app.Mainapp.Data,'Spikes') 
               ProbeView_Show_Spike_Positions(app.Mainapp.Data,app.Mainapp.ProbeViewProperties,app.UIAxes,app.SpikeCircleSizeMethod)
            else
                SpikeHandles = findobj(app.UIAxes, 'Tag', 'SpikePositions');
                if ~isempty(SpikeHandles)
                    delete(SpikeHandles)
                end
            end
        end

        % Menu selected function: SizeBySpikeNumberMenu
        function SizeBySpikeNumberMenuSelected(app, event)
            if ~isfield(app.Mainapp.Data,'Spikes')
                msgbox("No spike data found!");
                return;
            else
               if isempty(app.Mainapp.Data.Spikes)
                  msgbox("No spike data found!");
                  return;
               end
            end
            
            app.SpikeCircleSizeMethod = "NumSpikes";
            % overlay spike pos over probe
            if isfield(app.Mainapp.Data,'Spikes') 
               ProbeView_Show_Spike_Positions(app.Mainapp.Data,app.Mainapp.ProbeViewProperties,app.UIAxes,app.SpikeCircleSizeMethod)
            else
                SpikeHandles = findobj(app.UIAxes, 'Tag', 'SpikePositions');
                if ~isempty(SpikeHandles)
                    delete(SpikeHandles)
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ProbeViewUIFigure = uifigure('Visible', 'off');
            app.ProbeViewUIFigure.Name = 'Probe View';
            app.ProbeViewUIFigure.Icon = 'Logo.png';
            app.ProbeViewUIFigure.CloseRequestFcn = createCallbackFcn(app, @ProbeViewUIFigureCloseRequest, true);
            app.QuickSelectOptionsMenu = uimenu(app.ProbeViewUIFigure);
            app.QuickSelectOptionsMenu.Text = '   Quick Select Options   ';
            app.EverySecondRowMenu = uimenu(app.QuickSelectOptionsMenu);
            app.EverySecondRowMenu.MenuSelectedFcn = createCallbackFcn(app, @EverySecondRowMenuSelected, true);
            app.EverySecondRowMenu.Text = 'Every Second Row';
            app.JustLeftColumnMenu = uimenu(app.QuickSelectOptionsMenu);
            app.JustLeftColumnMenu.MenuSelectedFcn = createCallbackFcn(app, @JustLeftColumnMenuSelected, true);
            app.JustLeftColumnMenu.Text = 'Just Left Column';
            app.JustRightColumnMenu = uimenu(app.QuickSelectOptionsMenu);
            app.JustRightColumnMenu.MenuSelectedFcn = createCallbackFcn(app, @JustRightColumnMenuSelected, true);
            app.JustRightColumnMenu.Text = 'Just Right Column';
            app.EveryChannelMenu = uimenu(app.QuickSelectOptionsMenu);
            app.EveryChannelMenu.MenuSelectedFcn = createCallbackFcn(app, @EveryChannelMenuSelected, true);
            app.EveryChannelMenu.Text = 'Every Channel';
            app.SelectSpecificRowsMenu_2 = uimenu(app.QuickSelectOptionsMenu);
            app.SelectSpecificRowsMenu_2.MenuSelectedFcn = createCallbackFcn(app, @SelectSpecificRowsMenu_2Selected, true);
            app.SelectSpecificRowsMenu_2.Text = 'Select Specific Rows';
            app.SelectSpecificColumnsMenu = uimenu(app.QuickSelectOptionsMenu);
            app.SelectSpecificColumnsMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectSpecificColumnsMenuSelected, true);
            app.SelectSpecificColumnsMenu.Text = 'Select Specific Columns';
            app.HelpMenu = uimenu(app.ProbeViewUIFigure);
            app.HelpMenu.Text = '   Help   ';
            app.ShowHelpWindowMenu = uimenu(app.HelpMenu);
            app.ShowHelpWindowMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowHelpWindowMenuSelected, true);
            app.ShowHelpWindowMenu.Text = 'Show Help Window';
            app.PlotSpikesPositionsMenu = uimenu(app.ProbeViewUIFigure);
            app.PlotSpikesPositionsMenu.Text = '   Plot Spikes Positions   ';
            app.ShowSpikePositionsonProbeMenu = uimenu(app.PlotSpikesPositionsMenu);
            app.ShowSpikePositionsonProbeMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowSpikePositionsonProbeMenuSelected, true);
            app.ShowSpikePositionsonProbeMenu.Text = 'Show Spike Positions on Probe';
            app.HideSpikePositionsonProbeMenu = uimenu(app.PlotSpikesPositionsMenu);
            app.HideSpikePositionsonProbeMenu.MenuSelectedFcn = createCallbackFcn(app, @HideSpikePositionsonProbeMenuSelected, true);
            app.HideSpikePositionsonProbeMenu.Text = 'Hide Spike Positions on Probe';
            app.SizeByAmplitudeMenu = uimenu(app.PlotSpikesPositionsMenu);
            app.SizeByAmplitudeMenu.MenuSelectedFcn = createCallbackFcn(app, @SizeByAmplitudeMenuSelected, true);
            app.SizeByAmplitudeMenu.Text = 'Size By Amplitude';
            app.SizeBySpikeNumberMenu = uimenu(app.PlotSpikesPositionsMenu);
            app.SizeBySpikeNumberMenu.MenuSelectedFcn = createCallbackFcn(app, @SizeBySpikeNumberMenuSelected, true);
            app.SizeBySpikeNumberMenu.Text = 'Size By Spike Number';
            app.UIAxes = uiaxes(app.ProbeViewUIFigure);
            app.UIAxes2 = uiaxes(app.ProbeViewUIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.XTickLabel = '';
            app.UIAxes2.YTick = [];
            app.UIAxes2.ZTick = [];
            app.Panel = uipanel(app.ProbeViewUIFigure);
            app.ChangeforWindowDropDownLabel = uilabel(app.Panel);
            app.ChangeforWindowDropDownLabel.Text = 'Change for Window';
            app.ChangeforWindowDropDown = uidropdown(app.Panel);
            app.ChangeforWindowDropDown.Items = {'All Windows Opened', 'Main Window', 'Static Spectrum Window'};
            app.ChangeforWindowDropDown.Value = 'All Windows Opened';
            app.ChannelSelectionEditField = uieditfield(app.Panel, 'text');
            app.ChannelSelectionEditField.ValueChangedFcn = createCallbackFcn(app, @ChannelSelectionEditFieldValueChanged, true);
            app.ChannelSelectionMatlabexpressionsLabel = uilabel(app.Panel);
            app.ChannelSelectionMatlabexpressionsLabel.Text = {'Channel Selection '; '(Matlab expressions)'};
            app.ShowChannelSpacingCheckBox = uicheckbox(app.Panel);
            app.ShowChannelSpacingCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowChannelSpacingCheckBoxValueChanged, true);
            app.ShowChannelSpacingCheckBox.Text = '   Preserve Channel Locations in Plots';
            app.ShowChannelSpacingCheckBox.Value = true;
            app.ScrollandclicktosetactiveanalysischannelLabel = uilabel(app.ProbeViewUIFigure);
            app.ScrollandclicktosetactiveanalysischannelLabel.Text = {'Scroll and click to set active '; 'analysis channel!'};
            % Show the figure after all components are created
            app.ProbeViewUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Probe_View_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ProbeViewUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ProbeViewUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ProbeViewUIFigure)
        end
    end
end