classdef AskForResampleWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AskforResampleWindowUIFigure  matlab.ui.Figure
        FirstAddLowPassandDownsamplingManuallyButton  matlab.ui.control.StateButton
        ProceedjustwithNarrowbandButton  matlab.ui.control.StateButton
        ProceedwithResamplingButton   matlab.ui.control.StateButton
        InformationTextArea           matlab.ui.control.TextArea
        InformationTextAreaLabel      matlab.ui.control.Label
    end

    
    properties (Access = public)
        Mainapp = [];
        NarrowbandFilterSettings = [] % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, NarrowbandFilterSettings, Mainapp)
            
            movegui(app.AskforResampleWindowUIFigure, 'center');
            app.Mainapp = Mainapp;
            app = Utility_Change_Light_Dark_Mode(app,"AskForResampling");

            if strcmp(NarrowbandFilterSettings,"Narrowband")
                app.ProceedjustwithNarrowbandButton.Text = 'Proceed just with Narrowband';
                app.InformationTextArea.Value = ["Attention: In the current preprocessing pipeline, the signal is not downsampled before narrowband filtering. For high sampling frequencies this results in big filter orders necessary for sharp FIR-1 filter characteristics that can take a very long time to compute (thats why filter inspection of narrowband and band pass filters in the preprocessing window are done for a 1000Hz signal).";"You now have the option to first automatically add a low-pass filter and downsampling step to the pipeline (you can change the settings by overwriting the respective step in the pipeline). Secondly you can just proceed with the original sampling frequency and add the narrowband filter step. Lastly you can apply a low pass filter and downsampling component to the pipeline yourself before the narrowband filter. The last option will therefore NOT add the narrowband filter to the pipeline this time."];
            else % band stop
                app.ProceedjustwithNarrowbandButton.Text = 'Proceed just with Band-Stop';
                app.InformationTextArea.Value = ["Attention: In the current preprocessing pipeline, the signal is not downsampled before band-stop filtering. For high sampling frequencies this results in big filter orders necessary for sharp FIR-1 filter characteristics that can take a very long time to compute (thats why filter inspection of narrowband and band pass filters in the preprocessing window are done for a 1000Hz signal).";"You now have the option to first automatically add a low-pass filter and downsampling step to the pipeline (you can change the settings by overwriting the respective step in the pipeline). Secondly you can just proceed with the original sampling frequency and add the band-stop filter step. Lastly you can apply a low pass filter and downsampling component to the pipeline yourself before the band-stop filter. The last option will therefore NOT add the band-stop filter to the pipeline this time."];
            end
                    
            
        end

        % Value changed function: ProceedwithResamplingButton
        function ProceedwithResamplingButtonValueChanged(app, event)
            app.NarrowbandFilterSettings.Option = 1;
            % Resume execution to return the output
            uiresume(app.AskforResampleWindowUIFigure);
        end

        % Value changed function: ProceedjustwithNarrowbandButton
        function ProceedjustwithNarrowbandButtonValueChanged(app, event)
            app.NarrowbandFilterSettings.Option = 2;
            % Resume execution to return the output
            uiresume(app.AskforResampleWindowUIFigure);
        end

        % Value changed function: 
        % FirstAddLowPassandDownsamplingManuallyButton
        function FirstAddLowPassandDownsamplingManuallyButtonValueChanged(app, event)
            app.NarrowbandFilterSettings.Option = 3;
            % Resume execution to return the output
            uiresume(app.AskforResampleWindowUIFigure);
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AskforResampleWindowUIFigure = uifigure('Visible', 'off');
            app.AskforResampleWindowUIFigure.Name = 'Ask for Resample Window';
            app.AskforResampleWindowUIFigure.Icon = 'Logo.png';
            app.InformationTextAreaLabel = uilabel(app.AskforResampleWindowUIFigure);
            app.InformationTextAreaLabel.Text = 'Information';
            app.InformationTextArea = uitextarea(app.AskforResampleWindowUIFigure);
            app.InformationTextArea.Value = {'Attention: In the current preprocessing pipeline, the signal is not downsampled before narrowband filtering. For high sampling frequencies this results in big filter orders necessary for sharp FIR-1 filter characteristics that can take a very long time to compute (thats why filter inspection of narrowband and band pass filters in the preprocessing window are done for a 1000Hz signal). '; 'You now have the option to first automatically add a resampling step to the pipeline, which downsamples to a desired sample rate and applies an anti-aliasing filter. Secondly you can just proceed with the original sampling frequency and add the narrowband filter step. Lastly you can apply a low pass filter and downsampling component to the pipeline yourself before the narrowband filter. The last option will therefore NOT add the narrowband filter to the pipeline this time.'};
            app.ProceedwithResamplingButton = uibutton(app.AskforResampleWindowUIFigure, 'state');
            app.ProceedwithResamplingButton.ValueChangedFcn = createCallbackFcn(app, @ProceedwithResamplingButtonValueChanged, true);
            app.ProceedwithResamplingButton.Text = {'Automatically Add Low-Pass'; 'and Downsampling'};
            app.ProceedjustwithNarrowbandButton = uibutton(app.AskforResampleWindowUIFigure, 'state');
            app.ProceedjustwithNarrowbandButton.ValueChangedFcn = createCallbackFcn(app, @ProceedjustwithNarrowbandButtonValueChanged, true);
            app.ProceedjustwithNarrowbandButton.Text = {'Proceed just with'; 'Narrowband'};
            app.FirstAddLowPassandDownsamplingManuallyButton = uibutton(app.AskforResampleWindowUIFigure, 'state');
            app.FirstAddLowPassandDownsamplingManuallyButton.ValueChangedFcn = createCallbackFcn(app, @FirstAddLowPassandDownsamplingManuallyButtonValueChanged, true);
            app.FirstAddLowPassandDownsamplingManuallyButton.Text = {'Add Low-Pass and '; 'Downsampling Manually'};
            % Show the figure after all components are created
            app.AskforResampleWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AskForResampleWindow(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.AskforResampleWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.AskforResampleWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AskforResampleWindowUIFigure)
        end
    end
end