classdef Change_Plot_Speed < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ChannelSpacingSelectionUIFigure  matlab.ui.Figure
        TimetoJumpsPressEntertoConfirmLabel  matlab.ui.control.Label
        TimetoJumpsPressEntertoConfirmEditField  matlab.ui.control.EditField
        TextArea_4  matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        Mainapp = []; % Description
        Window = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp, Window)
            movegui(app.ChannelSpacingSelectionUIFigure, 'center');
            
            app.Mainapp = Mainapp;
            app.Window = Window;

            app = Utility_Change_Light_Dark_Mode(app,"ChangePlotSpeed");
            
            if strcmp(Window,"Speed")
                app.TextArea_4.Value = "Change the amount of time jumped per updated frame (in samples). Default is 1 sample. The lower the number, the slower the main window data plotplayback mode.";
                app.TimetoJumpsPressEntertoConfirmLabel.Text = "Samples to Jump. Press Enter to Confirm";
            else
                app.TextArea_4.Value = "Since plotting speed is also determined by the amount of data plotted and can go to fast for high frequency data, you can enter an additional delay added inbetween ech frame. Default is 0s.";
                app.TimetoJumpsPressEntertoConfirmLabel.Text = "Additional Delay [s]";
            end 

            if strcmp(Window,"Speed")
                app.TimetoJumpsPressEntertoConfirmEditField.Value = num2str(app.Mainapp.MovieTimeToJump);
            else
                app.TimetoJumpsPressEntertoConfirmEditField.Value = num2str(app.Mainapp.PlotAppearance.MainWindow.Data.AdditionalPlotDelay);
            end
        end

        % Value changed function: TimetoJumpsPressEntertoConfirmEditField
        function TimetoJumpsPressEntertoConfirmEditFieldValueChanged(app, event)
            if strcmp(app.Window,"Speed")
                [CorrectedValue] = Utility_SimpleCheckInputs(app.TimetoJumpsPressEntertoConfirmEditField.Value,"One",'0.02',0,0);
            else
                [CorrectedValue] = Utility_SimpleCheckInputs(app.TimetoJumpsPressEntertoConfirmEditField.Value,"One",'0',0,0);
            end

            if ~strcmp(CorrectedValue,app.TimetoJumpsPressEntertoConfirmEditField.Value)
                msgbox("Error: Wrong format. Please enter a single number.")
                return;
            end
            
            if strcmp(app.Window,"Speed")
                app.Mainapp.MovieTimeToJump = str2double(app.TimetoJumpsPressEntertoConfirmEditField.Value);
                app.Mainapp.ManuallyChangedPlotSpeed = 1;
                msgbox(strcat("Time to jump changed to ",num2str(app.Mainapp.MovieTimeToJump)))
            else
                app.Mainapp.PlotAppearance.MainWindow.Data.AdditionalPlotDelay = str2double(app.TimetoJumpsPressEntertoConfirmEditField.Value);
                msgbox(strcat("Additional plot delay changed to ",num2str(app.Mainapp.PlotAppearance.MainWindow.Data.AdditionalPlotDelay)))
            end
            
            delete(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ChannelSpacingSelectionUIFigure = uifigure('Visible', 'off');
            app.ChannelSpacingSelectionUIFigure.Name = 'Channel Spacing Selection';
            app.ChannelSpacingSelectionUIFigure.Icon = 'Logo.png';
            app.TextArea_4 = uitextarea(app.ChannelSpacingSelectionUIFigure);
            app.TextArea_4.Editable = 'off';
            app.TextArea_4.Value = {'The update speed of the main data plot is limited to 40 fps, but you can change the amount of time jumped per frame. Default is 0.02 '};
            app.TimetoJumpsPressEntertoConfirmEditField = uieditfield(app.ChannelSpacingSelectionUIFigure, 'text');
            app.TimetoJumpsPressEntertoConfirmEditField.ValueChangedFcn = createCallbackFcn(app, @TimetoJumpsPressEntertoConfirmEditFieldValueChanged, true);
            app.TimetoJumpsPressEntertoConfirmLabel = uilabel(app.ChannelSpacingSelectionUIFigure);
            app.TimetoJumpsPressEntertoConfirmLabel.Text = {'Time to Jump [s] '; 'Press Enter to Confirm'};
            % Show the figure after all components are created
            app.ChannelSpacingSelectionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Change_Plot_Speed(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ChannelSpacingSelectionUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ChannelSpacingSelectionUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ChannelSpacingSelectionUIFigure)
        end
    end
end