classdef Controls_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ControlsWindowsUIFigure  matlab.ui.Figure
        ProbeViewWindowLabel     matlab.ui.control.Label
        CloseButton              matlab.ui.control.Button
        ProbeViewWindowPanel     matlab.ui.container.Panel
        TextArea_2               matlab.ui.control.TextArea
        MainWindowPanel          matlab.ui.container.Panel
        MainWindowLabel          matlab.ui.control.Label
        TextArea                 matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        Mainapp = [] % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            movegui(app.ControlsWindowsUIFigure, 'center');            %% Predefine variables for the app to work
            
            app.Mainapp = Mainapp;
            app = Utility_Change_Light_Dark_Mode(app,"ControlsWindow");

        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ControlsWindowsUIFigure = uifigure('Visible', 'off');
            app.ControlsWindowsUIFigure.Name = 'Controls Windows';
            app.ControlsWindowsUIFigure.Icon = 'Logo.png';
            app.MainWindowPanel = uipanel(app.ControlsWindowsUIFigure);
            app.MainWindowPanel.Title = 'Main Window';
            app.TextArea = uitextarea(app.MainWindowPanel);
            app.TextArea.Value = {'You can hover over (almost) every element in every window to show tooltips. If you don''t need them, tooltips can be deactivated in the main window menu ''Extras'' under ''Tooltips''. If you don''t save the current selection, it is reset the next time NeuroMod is started.'; ''; 'The menu ''View'' offers to show different kinds of analysis plots as well as the possibility to customize the appearance of the plots (like colours, widths...). If you don''t save it (in the same menu tree), it is reset the next time NeuroMod is started.'; ''; 'Click on the data plot to show channel and time point when plotting individual lines. Click on the time plot under it to change which time points in the recording you are looking at. Alternatively use the ''<<'' or ''>>'' button to jump in time by the amount specified in the ''Time to Manipulate Main Plot'' field. Use the ''Current Time Span'' edit field to set the amount of time displayed in the main window.'; ''; 'Click the Movie button to play back data in a loop, increasing by n - data points each iteration. The number of data points can be changed by right clicking the movie button and an additional delay can be added to slow down playback speed. '; ''; 'Use the left and right arrow keys to jump in time and arrow up and arrow down to change the scale of data traces being plotted (click somewhere in the main window except of buttons or edit fields if it should not work). Alternatively scaling can be changed using the ''Data Line Spacing'' silder'};
            app.MainWindowLabel = uilabel(app.MainWindowPanel);
            app.MainWindowLabel.Text = 'Main Window';
            app.ProbeViewWindowPanel = uipanel(app.ControlsWindowsUIFigure);
            app.ProbeViewWindowPanel.Title = 'Probe View Window';
            app.TextArea_2 = uitextarea(app.ProbeViewWindowPanel);
            app.TextArea_2.Value = {'For probe designs with less than 2 channel rows, the whole probe is plotted on the left of the probe view window with a zoomed in channel selection plotted to the right of it. '; 'To change the portion of the probe the zoomed channel selection shows, click anywhere in the plot except of the zoomed in channel selection or use the mouse wheel while hovering over the plot. '; 'In turn click on any channel in the zoomed in channel selection to activate (becomes yellow) or deactivate it (becomes black/white). '; 'When the probe has more than 2 channel rows, only the full probe design without a zoomed in selection is plotted. You can still click any channel to activate or deactivate it.'; ''; 'Activating or deactivating a channel leads to the corresponding data trace (or whatever kind of data is shown for that channel) not being plotted in the analysis window(s) selected in the ''Change for Window'' dropdown menu on top of the probe view window. '; 'If the menu shows ''All Windows Opened'', the change is applied to the main window data plot as well as every analysis window in which you can not change channel manually. This means, that whenever a window contains a channel selection menu that is enabled (not greyed and possible to change), this is the sole channel analysed in the window.'; 'For example, the continuous spectrum analysis window offers a single channel spectrum analysis, for which the user can select the channel in a dropdown menu. Activating/deactivating channel in the probe view window does not affect this. '; 'However, when the user selects to plot the spectrum over depth (over every channel), the channel selection dropdown is disabled (cannot be changed anymore) and only active channel in the probe view window are analysed.'; ''; 'The more windows are opened the more computational overhead, so be aware for which windows you apply channel changes.'};
            app.CloseButton = uibutton(app.ControlsWindowsUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.ProbeViewWindowLabel = uilabel(app.ControlsWindowsUIFigure);
            app.ProbeViewWindowLabel.Text = 'Probe View Window';
            % Show the figure after all components are created
            app.ControlsWindowsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Controls_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ControlsWindowsUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ControlsWindowsUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ControlsWindowsUIFigure)
        end
    end
end