classdef Figure_Property_Change_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ChangePlotAppearanceWindowUIFigure  matlab.ui.Figure
        EnteraValueLabel  matlab.ui.control.Label
        EditField         matlab.ui.control.EditField
    end

    
    properties (Access = public)
        Figure = [] % Description
        Type = [] % Description+
        Mainapp = [];
        Plot = [];
        SelectedColor = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Figure, Type, Mainapp, Plot)
            movegui(app.ChangePlotAppearanceWindowUIFigure, 'center');
            
            app = Utility_Change_Light_Dark_Mode(app,"FigureChange");
            
            app.Figure = Figure;
            app.Type = Type;
            app.Mainapp = Mainapp;
            app.Plot = Plot;
            
            texttoshow = strcat('Please enter new ',convertStringsToChars(Type));
            
            app.EnteraValueLabel.Text = texttoshow;
            
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            %% Main Window Data
            % Labels 
            if strcmp(app.Plot,"MainWindowDataTitle")
                if app.Mainapp.RawDataPlotCheckBox.Value == 1
                    app.Mainapp.PlotAppearance.MainWindow.Data.Title.Raw = value;
                elseif app.Mainapp.PreprocDataPlotCheckBox.Value == 1
                    app.Mainapp.PlotAppearance.MainWindow.Data.Title.Preprocessed = value;
                end
            elseif strcmp(app.Plot,"MainWindowDataXlabel")
                app.Mainapp.PlotAppearance.MainWindow.Data.MainXLabel = value;
            elseif strcmp(app.Plot,"MainWindowDataYlabel")
                app.Mainapp.PlotAppearance.MainWindow.Data.MainYLabel = value;
            elseif strcmp(app.Plot,"MainWindowDataFontSize")
                app.Mainapp.PlotAppearance.MainWindow.Data.MainFontSize = str2double(value);
                app.Figure.FontSize = app.Mainapp.PlotAppearance.MainWindow.Data.MainFontSize;
            %Line Width
            elseif strcmp(app.Plot,"MainWindowDataLineWidthData")
                app.Mainapp.PlotAppearance.MainWindow.Data.LineWidth.MainData = str2double(value);
            elseif strcmp(app.Plot,"MainWindowDataLineWidthEvent")
                app.Mainapp.PlotAppearance.MainWindow.Data.LineWidth.MainEvents = str2double(value);
            elseif strcmp(app.Plot,"MainWindowDataLineWidthSpikes")
                app.Mainapp.PlotAppearance.MainWindow.Data.LineWidth.MainSpikes = str2double(value);
            % Colors
            elseif strcmp(app.Plot,"MainWindowDataColorSpikes")
                app.Mainapp.PlotAppearance.MainWindow.Data.Color.MainSpikes = str2double(value);
            elseif strcmp(app.Plot,"MainWindowDataColorEvents")
                app.Mainapp.PlotAppearance.MainWindow.Data.Color.MainEvents = str2double(value);
            elseif strcmp(app.Plot,"MainWindowDataColorBackGround")
                app.Mainapp.PlotAppearance.MainWindow.Data.Color.MainBackground = str2double(value);
                app.Figure.Color = app.Mainapp.PlotAppearance.MainWindow.Data.Color.MainBackground;
            elseif strcmp(app.Plot,"MainWindowTriggerNrTextFontSize")
                app.Mainapp.PlotAppearance.MainWindow.Data.TriggerNrTextFontSize = str2double(value);
            elseif strcmp(app.Plot,"MainWindowGridDataLineWidthData")
                app.Mainapp.PlotAppearance.MainWindow.Data.LineWidth.GridTraceData = str2double(value);
            elseif strcmp(app.Plot,"MainWindowGridEventLineWidthData")
                app.Mainapp.PlotAppearance.MainWindow.Data.LineWidth.GridTraceEvents = str2double(value);
            % TrailGrid samples
            elseif strcmp(app.Plot,"MainWindowNumSamplesGridTrail")
                app.Mainapp.PlotAppearance.MainWindow.Data.GridTailSamples = str2double(value);
            %% Main Window Time
            elseif strcmp(app.Plot,"MainWindowTimeTitle")
                app.Mainapp.PlotAppearance.MainWindow.Data.TimeTitle = value;
            elseif strcmp(app.Plot,"MainWindowTimeFontSize")
                app.Mainapp.PlotAppearance.MainWindow.Data.TimeFontSize = str2double(value);
                app.Figure.FontSize = app.Mainapp.PlotAppearance.MainWindow.Data.TimeFontSize;
            elseif strcmp(app.Plot,"MainWindowTimeYlabel")
                app.Mainapp.PlotAppearance.MainWindow.Data.TimeYLabel = value;
            elseif strcmp(app.Plot,"MainWindowTimeXlabel")
                app.Mainapp.PlotAppearance.MainWindow.Data.TimeXLabel = value;
            %Line Width
            elseif strcmp(app.Plot,"MainWindowTimeLineWidthRectangle")
                app.Mainapp.PlotAppearance.MainWindow.Data.LineWidth.TimeRectangle = str2double(value);
            elseif strcmp(app.Plot,"MainWindowTimeLineWidthEvent")
                app.Mainapp.PlotAppearance.MainWindow.Data.LineWidth.TimeEvents = str2double(value);
            % Colors
            elseif strcmp(app.Plot,"MainWindowTimeColorRectangle")
                app.Mainapp.PlotAppearance.MainWindow.Data.Color.TimeRectangle = str2double(value);
            elseif strcmp(app.Plot,"MainWindowTimeColorEvents")
                app.Mainapp.PlotAppearance.MainWindow.Data.Color.TimeEvents = str2double(value);
            elseif strcmp(app.Plot,"MainWindowTimeBackGround")
                app.Mainapp.PlotAppearance.MainWindow.Data.Color.TimeBackground = str2double(value);
                app.Figure.Color = app.Mainapp.PlotAppearance.MainWindow.Data.Color.TimeBackground;
            end
            %% Cont. Power Spectrum

            if strcmp(app.Plot,"SpectrumFontSize")
                app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeFontSize = str2double(value);
            elseif strcmp(app.Plot,"SpectrumLineWidth")
                app.Mainapp.PlotAppearance.SpectrumWindow.Data.SpectrumLinwWidth = str2double(value);
            elseif strcmp(app.Plot,"SpectrumWindowDataXlabel")
                app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeXLabel = value;
            elseif strcmp(app.Plot,"SpectrumWindowDataYlabel")
                app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeYLabel = value;
            elseif strcmp(app.Plot,"EventSpectrumGridDataLineWidth")
                app.Mainapp.PlotAppearance.SpectrumWindow.Data.GridTraceDataWidth = str2double(value);
            elseif strcmp(app.Plot,"EventSpectrumGridEventLineWidth")
                app.Mainapp.PlotAppearance.SpectrumWindow.Data.GridTraceEventWidth = str2double(value);
            end

            %% Event Related ERP
            % single ERP
            if strcmp(app.Plot,"SingleERPWindowDataFontSize")
                app.Mainapp.PlotAppearance.ERPWindow.SingleERP.FontSize = str2double(value);
            elseif strcmp(app.Plot,"SingleERPWindowXLabel")
                app.Mainapp.PlotAppearance.ERPWindow.SingleERP.XLabel = value;
            elseif strcmp(app.Plot,"SingleERPWindowYLabel")
                app.Mainapp.PlotAppearance.ERPWindow.SingleERP.YLabel = value;
            elseif strcmp(app.Plot,"SingleERPLineWidthEvents")
                app.Mainapp.PlotAppearance.ERPWindow.SingleERP.EventLineWidth = str2double(value);
            elseif strcmp(app.Plot,"SingleERPLineWidthMean")
                app.Mainapp.PlotAppearance.ERPWindow.SingleERP.MeanLineWidth = str2double(value);
            elseif strcmp(app.Plot,"SingleERPLineWidthTrigger")
                app.Mainapp.PlotAppearance.ERPWindow.SingleERP.TriggerLineWidth = str2double(value);
            end
            % Multiple ERP
            if strcmp(app.Plot,"MultipleERPWindowDataFontSize")
                app.Mainapp.PlotAppearance.ERPWindow.MultipleERP.FontSize = str2double(value);
            elseif strcmp(app.Plot,"MultipleERPWindowXLabel")
                app.Mainapp.PlotAppearance.ERPWindow.MultipleERP.XLabel = value;
            elseif strcmp(app.Plot,"MultipleERPWindowYLabel")
                app.Mainapp.PlotAppearance.ERPWindow.MultipleERP.YLabel = value;
            elseif strcmp(app.Plot,"MultipleERPLineWidthMean")
                app.Mainapp.PlotAppearance.ERPWindow.MultipleERP.MeanLineWidth = str2double(value);
            elseif strcmp(app.Plot,"MultipleERPLineWidthTrigger")
                app.Mainapp.PlotAppearance.ERPWindow.MultipleERP.TriggerLineWidth = str2double(value);
            end
            % Grid ERP
            if strcmp(app.Plot,"ERPGridDataLineWidth")
                app.Mainapp.PlotAppearance.ERPWindow.Grid.GridDataLineWidth = str2double(value);
            elseif strcmp(app.Plot,"ERPGridEventLineWidth")
                app.Mainapp.PlotAppearance.ERPWindow.Grid.GridEventLineWidth = str2double(value);
            end

            %% Event Related CSD
            if strcmp(app.Plot,"CSDWindowDataFontSize")
                app.Mainapp.PlotAppearance.CSDWindow.FontSize = str2double(value);
            elseif strcmp(app.Plot,"CSDXLabel")
                app.Mainapp.PlotAppearance.CSDWindow.XLabel = value;
            elseif strcmp(app.Plot,"CSDYLabel")
                app.Mainapp.PlotAppearance.CSDWindow.YLabel = value;
            elseif strcmp(app.Plot,"CSDCLabel")
                app.Mainapp.PlotAppearance.CSDWindow.CLabel = value;
            elseif strcmp(app.Plot,"CSDEventLineWidth")
                app.Mainapp.PlotAppearance.CSDWindow.TriggerLineWidth = str2double(value);
            end

            %% Event Related TF
            if strcmp(app.Plot,"TFWindowDataFontSize")
                app.Mainapp.PlotAppearance.TFWindow.FontSize = str2double(value);
            elseif strcmp(app.Plot,"TFXLabel")
                app.Mainapp.PlotAppearance.TFWindow.XLabel = value;
            elseif strcmp(app.Plot,"TFYLabel")
                app.Mainapp.PlotAppearance.TFWindow.YLabel = value;
            elseif strcmp(app.Plot,"TFCLabel")
                app.Mainapp.PlotAppearance.TFWindow.CLabel = value;
            elseif strcmp(app.Plot,"TFEventLineWidth")
                app.Mainapp.PlotAppearance.TFWindow.TriggerLineWidth = str2double(value);
            end

            %% Live CSD
            if strcmp(app.Plot,"LiveCSDWindowDataFontSize")
                app.Mainapp.PlotAppearance.LiveCSDWindow.FontSize = str2double(value);
            elseif strcmp(app.Plot,"LiveCSDXlabel")
                app.Mainapp.PlotAppearance.LiveCSDWindow.XLabel = value;
            elseif strcmp(app.Plot,"LiveCSDYlabel")
                app.Mainapp.PlotAppearance.LiveCSDWindow.YLabel = value;
            elseif strcmp(app.Plot,"LiveCSDClabel")
                app.Mainapp.PlotAppearance.LiveCSDWindow.CLabel = value;
            elseif strcmp(app.Plot,"LiveCSDEventLineWidth")
                app.Mainapp.PlotAppearance.LiveCSDWindow.TriggerLineWidth = str2double(value);
            end

            %% Live Power Estimate
            if strcmp(app.Plot,"LivePEWindowDataFontSize")
                app.Mainapp.PlotAppearance.LivePowerEstimateWindow.FontSize = str2double(value);
            elseif strcmp(app.Plot,"LivePEWindowXLabel")
                app.Mainapp.PlotAppearance.LivePowerEstimateWindow.XLabel = value;
            elseif strcmp(app.Plot,"LivePEWindowYLabel")
                app.Mainapp.PlotAppearance.LivePowerEstimateWindow.YLabel = value;
            end

            %% Live Spectrogram
            if strcmp(app.Plot,"LiveSpecWindowDataFontSize")
                app.Mainapp.PlotAppearance.LiveSpectrogramWindow.FontSize = str2double(value);
            elseif strcmp(app.Plot,"LiveSpecWindowXLabel")
                app.Mainapp.PlotAppearance.LiveSpectrogramWindow.XLabel = value;
            elseif strcmp(app.Plot,"LiveSpecWindowYLabel")
                app.Mainapp.PlotAppearance.LiveSpectrogramWindow.YLabel = value;
            elseif strcmp(app.Plot,"LiveSpecWindowCLabel")
                app.Mainapp.PlotAppearance.LiveSpectrogramWindow.CLabel = value;
            end

            %% Live Spike Rate
            if strcmp(app.Plot,"LiveSpikeRateFontSize")
                app.Mainapp.PlotAppearance.LiveSpikeRateWindow.FontSize = str2double(value);
            elseif strcmp(app.Plot,"LiveSpikeRateXLabel")
                app.Mainapp.PlotAppearance.LiveSpikeRateWindow.XLabel = value;
            elseif strcmp(app.Plot,"LiveSpikeRateYLabel")
                app.Mainapp.PlotAppearance.LiveSpikeRateWindow.YLabel = value;
            end

            %% Event Related Spikes
            if strcmp(app.Plot,"EventSpikeWindowDataFontSize")
                app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotFontSize = str2double(value);
            elseif strcmp(app.Plot,"EventSpikeWindowSpikeLineWidth")
                app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotSpikeWidth = str2double(value);
            elseif strcmp(app.Plot,"EventSpikeWindowTriggerLineWidth")
                app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotTriggerWidth = str2double(value);
            elseif strcmp(app.Plot,"EventSpikeWindowSpikeXLabel")
                app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotXLabel = value;
            elseif strcmp(app.Plot,"EventSpikeWindowSpikeYLabel")
                app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotYLabel = value;
            end

            %% Event Related Spikes SPike Rate over time
            if strcmp(app.Plot,"EventSpikeWindowSpikeRateTimeFontSize")
                app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRTimePlotFontSize = str2double(value);
            elseif strcmp(app.Plot,"EventSpikeWindowSpikeRateTimeXLabel")
                app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRTimePlotXLabel = value;
            elseif strcmp(app.Plot,"EventSpikeWindowSpikeRateTimeYLabel")
                app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRTimePlotYLabel = value;
            end

            %% Event Related Spikes Spike Rate over channel
            if strcmp(app.Plot,"EventSpikeWindowSpikeRateChannelFontSize")
                app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRChannelPlotFontSize = str2double(value);
            elseif strcmp(app.Plot,"EventSpikeWindowSpikeRateChannelXLabel")
                app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRChannelPlotXLabel = value;
            elseif strcmp(app.Plot,"EventSpikeWindowSpikeRateChannelYLabel")
                app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRChannelPlotYLabel = value;
            end


            %% Main Data phase sync plot
            if strcmp(app.Plot,"MainDataphasesyncAllFontSize")
                app.Mainapp.PlotAppearance.PhaseSyncPlots.AlltoAllFontSize = str2double(value);
            elseif strcmp(app.Plot,"MainDataphasesyncAllXLabel")
                app.Mainapp.PlotAppearance.PhaseSyncPlots.AlltoAllXLabel = value;
            elseif strcmp(app.Plot,"MainDataphasesyncAllYLabel")
                app.Mainapp.PlotAppearance.PhaseSyncPlots.AlltoAllYLabel = value;
            elseif strcmp(app.Plot,"MainDataphasesyncAllTitle")
                app.Mainapp.PlotAppearance.PhaseSyncPlots.AlltoAllTitle = value;
            elseif strcmp(app.Plot,"MainDataphasesyncCLabel")
                app.Mainapp.PlotAppearance.PhaseSyncPlots.AlltoAllCLabel = value;
            end
            if strcmp(app.Plot,"MainDataphasesyncDiffsFontSize")
                app.Mainapp.PlotAppearance.PhaseSyncPlots.AngleDiffFontSize = str2double(value);
            elseif strcmp(app.Plot,"MainDataphasesyncDiffsXLabel")
                app.Mainapp.PlotAppearance.PhaseSyncPlots.AngleDiffXLabel = value;
            elseif strcmp(app.Plot,"MainDataphasesyncDiffsYLabel")
                app.Mainapp.PlotAppearance.PhaseSyncPlots.AngleDiffYLabel = value;
            elseif strcmp(app.Plot,"MainDataphasesyncDiffsTitle")
                app.Mainapp.PlotAppearance.PhaseSyncPlots.AngleDiffTitle = value;
            elseif strcmp(app.Plot,"MainDataphasesyncDiffsLineWidthPhases")
                app.Mainapp.PlotAppearance.PhaseSyncPlots.AngleDiffAnglesWidth = str2double(value);
            elseif strcmp(app.Plot,"MainDataphasesyncDiffsLineWidthAverage")
                app.Mainapp.PlotAppearance.PhaseSyncPlots.AngleDiffMeanWidth = str2double(value);
            end

            if strcmp(app.Plot,"MainDataPhaseAnglesFontSize")
                app.Mainapp.PlotAppearance.PhaseSyncPlots.PATimeSeriesFontSize = str2double(value);
            elseif strcmp(app.Plot,"MainDataPhaseAnglesXLabel")
                app.Mainapp.PlotAppearance.PhaseSyncPlots.PATimeSeriesXLabel = value;
            elseif strcmp(app.Plot,"MainDataPhaseAnglesYLabel")
                app.Mainapp.PlotAppearance.PhaseSyncPlots.PATimeSeriesYLabel = value;
            elseif strcmp(app.Plot,"MainDataPhaseAnglesTitle")
                app.Mainapp.PlotAppearance.PhaseSyncPlots.PATimeSeriesTitle = value;
            elseif strcmp(app.Plot,"MainDataPhaseAnglesLineWidthPhases")
                app.Mainapp.PlotAppearance.PhaseSyncPlots.PATimeSeriesWidth = str2double(value);
            end

            if strcmp(app.Plot,"MainDataPhaseEnvelopsFontSize")
                app.Mainapp.PlotAppearance.PhaseSyncPlots.PAAmpFontSize = str2double(value);
            elseif strcmp(app.Plot,"MainDataPhaseEnvelopsXLabel")
                app.Mainapp.PlotAppearance.PhaseSyncPlots.PAAmpXLabel = value;
            elseif strcmp(app.Plot,"MainDataPhaseEnvelopsYLabel")
                app.Mainapp.PlotAppearance.PhaseSyncPlots.PAAmpYLabel = value;
            elseif strcmp(app.Plot,"MainDataPhaseEnvelopsTitle")
                app.Mainapp.PlotAppearance.PhaseSyncPlots.PAAmpTitle = value;
            elseif strcmp(app.Plot,"MainDataPhaseEnvelopsLineWidthPhases")
                app.Mainapp.PlotAppearance.PhaseSyncPlots.PAAmpSeriesWidth  = str2double(value);
            end

            %% Delete event indices window
            if strcmp(app.Plot,"DeleteIndiceChangeTimwWindow")

                app.Mainapp.EventIndiceRejectionWindow.TimearoundEvent = str2double(strsplit(value,','));
                if strcmp(app.Mainapp.EventIndiceRejectionWindow.DataToExtractFromDropDown.Value,"Preprocessed Data") && isfield(app.Mainapp.Data.Info,'DownsampledSampleRate')
                    app.Mainapp.EventIndiceRejectionWindow.EventTime = 0-app.Mainapp.EventIndiceRejectionWindow.TimearoundEvent(1):1/app.Mainapp.Data.Info.DownsampledSampleRate:app.Mainapp.EventIndiceRejectionWindow.TimearoundEvent(2);
                else
                    app.Mainapp.EventIndiceRejectionWindow.EventTime = 0-app.Mainapp.EventIndiceRejectionWindow.TimearoundEvent(1):1/app.Mainapp.Data.Info.NativeSamplingRate:app.Mainapp.EventIndiceRejectionWindow.TimearoundEvent(2);
                end

                %% -------------------- Setup trigger --------------------
                if ~strcmp(app.Mainapp.EventIndiceRejectionWindow.EventstoPlotDropDown.Value,"Mean over all Trigger")
                    EventToPlot = str2double(app.Mainapp.EventIndiceRejectionWindow.EventstoPlotDropDown.Value);
                else
                    EventToPlot = 1:size(app.Mainapp.Data.EventRelatedData,2);
                end

                %% -------------------- Extract Event Related Data --------------------
                [app.Mainapp.Data,~] = Event_Module_Extract_Event_Related_Data(app.Mainapp.Data,app.Mainapp.EventIndiceRejectionWindow.EventChannelforStimulationDropDown.Value,app.Mainapp.EventIndiceRejectionWindow.TimearoundEvent,app.Mainapp.EventIndiceRejectionWindow.DataToExtractFromDropDown.Value,"Raw Event Related Data");
                
                %% Extract and plot 
                Organize_Plot_Trigger_Indices(app.Mainapp.Data,app.Mainapp.EventIndiceRejectionWindow.UIAxes,EventToPlot,app.Mainapp.EventIndiceRejectionWindow.EventTime,app.Mainapp.ActiveChannel,app.Mainapp.EventIndiceRejectionWindow.EventstoPlotDropDown.Value,app.Mainapp.EventIndiceRejectionWindow.Slider.Value,app.Mainapp.EventIndiceRejectionWindow.EventChannelforStimulationDropDown.Value,app.Mainapp.EventIndiceRejectionWindow.TimearoundEvent,app.Mainapp.EventIndiceRejectionWindow.DataToExtractFromDropDown.Value,"Raw Event Related Data",app.Mainapp.EventIndiceRejectionWindow.ColorMap)

            end
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ChangePlotAppearanceWindowUIFigure = uifigure('Visible', 'off');
            app.ChangePlotAppearanceWindowUIFigure.Name = 'Change Plot Appearance Window';
            app.ChangePlotAppearanceWindowUIFigure.Icon = 'Logo.png';
            app.EditField = uieditfield(app.ChangePlotAppearanceWindowUIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EnteraValueLabel = uilabel(app.ChangePlotAppearanceWindowUIFigure);
            app.EnteraValueLabel.Text = 'Enter a Value';
            % Show the figure after all components are created
            app.ChangePlotAppearanceWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Figure_Property_Change_Window(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ChangePlotAppearanceWindowUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ChangePlotAppearanceWindowUIFigure)
        end
    end
end