classdef Manage_Dataset_Components < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ManageDatasetComponentsWindowUIFigure  matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        RightPanel                    matlab.ui.container.Panel
        CloseButton                   matlab.ui.control.Button
        TextArea                      matlab.ui.control.TextArea
        ManagementOptionsPanel        matlab.ui.container.Panel
        ExportComponenttoWorkSpaceButton  matlab.ui.control.Button
        ManagementOptionsLabel        matlab.ui.control.Label
        ExportFormatDropDownLabel_2   matlab.ui.control.Label
        ExportFormatDropDown          matlab.ui.control.DropDown
        ExportFormatDropDownLabel     matlab.ui.control.Label
        ExportDatasetComponentButton  matlab.ui.control.Button
        DeleteDatasetComponentButton  matlab.ui.control.Button
        DropDown                      matlab.ui.control.DropDown
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Mainapp = []; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            movegui(app.ManageDatasetComponentsWindowUIFigure, 'center');
            
            app.Mainapp = Mainapp;
            
            app = Utility_Change_Light_Dark_Mode(app,"ManageDataset");
            %% Populate fields of this GUI with Dataset components
            
            % Get the field names of the structure
            fieldNames = fieldnames(app.Mainapp.Data);
            
            % Convert the field names to a single string
            fieldNamesString = strjoin(fieldNames, ', ');
            
            texttoshow = {};

            if ~isempty(app.Mainapp.MainWindowPlotData)
                texttoshow{1} = strcat("1.: Main Window Plot Data with Dimension: ",num2str(size(app.Mainapp.MainWindowPlotData)));
                elements = 1;
            else
                elements = 0;
            end

            for i = 1:length(fieldNames)
                
                if strcmp(fieldNames(i),"Raw") || strcmp(fieldNames(i),"Preprocessed")
                    elements = elements+1;
                    texttoshow{end+1} = strcat(num2str(elements),".: ",fieldNames{i}," Data");
                elseif strcmp(fieldNames(i),"Events")
                    elements = elements+1;
                    texttoshow{end+1} = strcat(num2str(elements),".: ",fieldNames{i}," Data; Cell array with Dimension: ",num2str(size(app.Mainapp.Data.Events)));
                    for nevents = 1:length(app.Mainapp.Data.Events)
                        texttoshow{end+1} = [strcat("Event Nr ", num2str(nevents)," contains ",num2str(length(app.Mainapp.Data.Events{nevents}))," Trigger.")];
                    end
                    elements = elements+nevents;
                elseif strcmp(fieldNames(i),"Spikes")
                    elements = elements+1;
                    SpikeFieldnames = fieldnames(app.Mainapp.Data.Spikes);
                    texttoshow{end+1} = strcat(num2str(elements),".: ",fieldNames{i}," Data; Structure with ",num2str(length(SpikeFieldnames))," Fields.");
                    for nspikefields = 1:length(SpikeFieldnames)
                        if strcmp(SpikeFieldnames{nspikefields},"SpikeTimes")
                            CurrentSpikeFieldname = SpikeFieldnames{nspikefields};
                            texttoshow{end+1} = strcat("Spike Field ", CurrentSpikeFieldname," contains ",num2str(size(app.Mainapp.Data.Spikes.(CurrentSpikeFieldname)))," Elements.");
                        end
                    end
                    elements = elements+1;
                elseif strcmp(fieldNames(i),"Info")
                    elements = elements+1;
                    InfoFieldnames = fieldnames(app.Mainapp.Data.Info);
                    texttoshow{end+1} = strcat(num2str(elements),".: ",fieldNames{i}," Data; Structure with ",num2str(length(InfoFieldnames))," Fields.");
                else
                    elements = elements+1;
                    CurrentFieldname = fieldNames{i};
                    texttoshow{end+1} = strcat(num2str(elements),".: ",fieldNames{i},"; Dimension: ",num2str(size(app.Mainapp.Data.(CurrentFieldname))));
                end
            end

            texttoshow = ["Export or delete dataset components. Due to their length, raw and preprocessed data can be exported as .dat file (saved in double format) or .mat file. The time vector can only be exported as .mat. Event related data and preprocessed event related data can only be exported as .mat files since 3 dimensional matrices can hardly be saved in a sensible excel or .txt. file. Standard folder the exported data is saved to is 'GUI_Path/Analysis Results'. The following dataset components where found:","",texttoshow];
            % Display the field names in the Text Area
            app.TextArea.Value = texttoshow;

            texttoshow = {};
            elements = 1;

            for i = 1:length(fieldNames)
                if strcmp(fieldNames{i},'Spikes')
                    texttoshow{elements} = 'Spikes';
                    elements = elements+1;
                else
                    texttoshow{elements} = fieldNames{i};
                    elements = elements+1;
                end
            end
            
            if ~isempty(app.Mainapp.MainWindowPlotData)
                texttoshow{end+1} = 'Main Window Plot Data';
            end

            app.DropDown.Items = texttoshow;

            value = app.DropDown.Value;

            % Export restrictions    
            if strcmp(value,"EventRelatedData") || strcmp(value,"PreprocessedEventRelatedData") || strcmp(value,"Time") || strcmp(value,"TimeDownsampled")
                app.ExportFormatDropDown.Items = {};
                app.ExportFormatDropDown.Items{1} = '.mat';
            elseif strcmp(value,"Raw") || strcmp(value,"Preprocessed")
                app.ExportFormatDropDown.Items = {};
                app.ExportFormatDropDown.Items{1} = '.mat';
                app.ExportFormatDropDown.Items{2} = '.dat';
            else
                app.ExportFormatDropDown.Items = {};
                app.ExportFormatDropDown.Items{1} = '.mat';
                app.ExportFormatDropDown.Items{2} = '.txt';
                app.ExportFormatDropDown.Items{3} = '.xlsx';
            end
            % Delete restrictions
            if strcmp(value,"Time") || strcmp(value,"Info") || strcmp(value,"TimeDownsampled") || strcmp(value,"Raw")
                app.DeleteDatasetComponentButton.Enable = "off";
            else
                app.DeleteDatasetComponentButton.Enable = "on";
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            try
                app.Mainapp.Manage_Dataset_ComponentsWindow = [];
            end
            
            delete(app);
        end

        % Button pushed function: DeleteDatasetComponentButton
        function DeleteDatasetComponentButtonPushed(app, event)
            flagdownsampled = 0;
            CUrrentTimeShown = [];
            if strcmp(app.DropDown.Value,'Preprocessed')
                if isfield(app.Mainapp.Data.Info,'DownsampledSampleRate')
                    flagdownsampled = 1;
                    CUrrentTimeShown = app.Mainapp.Data.TimeDownsampled(app.Mainapp.CurrentTimePoints);
                end
            end

            [app.Mainapp.Data,Error] = Organize_Delete_Dataset_Components(app.Mainapp.Data,app.DropDown.Value);

            if Error == 0
                                
                [app.Mainapp] = Organize_Set_MainWindow_Dropdown(app.Mainapp,app.Mainapp.Data);
    
                Utility_Show_Info_Loaded_Data(app.Mainapp);
                
                KeepSpikes = 0;
                KeepEvents = 0;
                if isfield(app.Mainapp.Data,'Spikes') && strcmp(app.Mainapp.Plotspikes,"Spikes")
                    KeepSpikes = 1;
                end
                if isfield(app.Mainapp.Data,'Events') && strcmp(app.Mainapp.PlotEvents,"Events")
                    KeepEvents = 1;
                end
                
                if flagdownsampled == 1
                    if ~isempty(CUrrentTimeShown)
                        TimeinSecs = CUrrentTimeShown;
                    end
                    % Calculate the absolute differences
                    differences = abs(app.Mainapp.Data.Time - TimeinSecs);
                    
                    % Find the index of the minimum difference
                    [~, app.Mainapp.CurrentTimePoints] = min(differences);
                end
                   
                Organize_Reset_Main_Plot(app.Mainapp,0,KeepEvents,KeepSpikes,0,app.Mainapp.CurrentTimePoints);
                
                %% Populate fields of this GUI with Dataset components
            
                % Get the field names of the structure
                fieldNames = fieldnames(app.Mainapp.Data);
                
                % Convert the field names to a single string
                fieldNamesString = strjoin(fieldNames, ', ');
                
                texttoshow = {};
                elements = 0;
                for i = 1:length(fieldNames)
                    if strcmp(fieldNames(i),"Raw") || strcmp(fieldNames(i),"Preprocessed")
                        elements = elements+1;
                        texttoshow{end+1} = strcat(num2str(elements),".: ",fieldNames{i}," Data");
                    elseif strcmp(fieldNames(i),"Events")
                        elements = elements+1;
                        texttoshow{end+1} = strcat(num2str(elements),".: ",fieldNames{i}," Data; Cell array with Dimension: ",num2str(size(app.Mainapp.Data.Events)));
                        for nevents = 1:length(app.Mainapp.Data.Events)
                            texttoshow{end+1} = [strcat("Event Nr ", num2str(nevents)," contains ",num2str(length(app.Mainapp.Data.Events{nevents}))," Trigger.")];
                        end
                        elements = elements+nevents;
                    elseif strcmp(fieldNames(i),"Spikes")
                        elements = elements+1;
                        SpikeFieldnames = fieldnames(app.Mainapp.Data.Spikes);
                        texttoshow{end+1} = strcat(num2str(elements),".: ",fieldNames{i}," Data; Structure with ",num2str(length(SpikeFieldnames))," Fields.");
                        for nspikefields = 1:length(SpikeFieldnames)
                            if strcmp(SpikeFieldnames{nspikefields},"SpikeTimes")
                                CurrentSpikeFieldname = SpikeFieldnames{nspikefields};
                                texttoshow{end+1} = strcat("Spike Field ", CurrentSpikeFieldname," contains ",num2str(size(app.Mainapp.Data.Spikes.(CurrentSpikeFieldname)))," Elements.");
                            end
                        end
                        elements = elements+1;
                    elseif strcmp(fieldNames(i),"Info")
                        elements = elements+1;
                        InfoFieldnames = fieldnames(app.Mainapp.Data.Info);
                        texttoshow{end+1} = strcat(num2str(elements-1),".: ",fieldNames{i}," Data; Structure with ",num2str(length(InfoFieldnames))," Fields.");
                    else
                        elements = elements+1;
                        CurrentFieldname = fieldNames{i};
                        texttoshow{end+1} = strcat(num2str(elements-1),".: ",fieldNames{i},"; Dimension: ",num2str(size(app.Mainapp.Data.(CurrentFieldname))));
                    end
                end
    
                texttoshow = ["Export or delete dataset components. Due to their length, raw and preprocessed data can be exported as .dat file (saved in double format) or .mat file. The time vector can only be exported as .mat. Event related data and preprocessed event related data can only be exported as .mat files since 3 dimensional matrices can hardly be saved in a sensible excel or .txt. file. Standard folder the exported data is saved to is 'GUI_Path/Analysis Results'. The following dataset components where found:","",texttoshow];
                % Display the field names in the Text Area
                app.TextArea.Value = texttoshow;
    
                texttoshow = {};
                elements = 1;
    
                for i = 1:length(fieldNames)
                    if strcmp(fieldNames{i},'Spikes')
                        texttoshow{elements} = 'Spikes';
                        elements = elements+1;
                    else
                        texttoshow{elements} = fieldNames{i};
                        elements = elements+1;
                    end
                end
    
                app.DropDown.Items = texttoshow;
    
                value = app.DropDown.Value;
    
                % Export restrictions    
                if strcmp(value,"Raw") || strcmp(value,"Preprocessed") || strcmp(value,"EventRelatedData") || strcmp(value,"PreprocessedEventRelatedData") || strcmp(value,"Time") || strcmp(value,"TimeDownsampled")
                    app.ExportFormatDropDown.Items = {};
                    app.ExportFormatDropDown.Items{1} = '.mat';
                else
                    app.ExportFormatDropDown.Items = {};
                    app.ExportFormatDropDown.Items{1} = '.mat';
                    app.ExportFormatDropDown.Items{2} = '.txt';
                    app.ExportFormatDropDown.Items{3} = '.xlsx';
                end
                % Delete restrictions
                if strcmp(value,"Time") || strcmp(value,"Info") || strcmp(value,"TimeDownsampled") || strcmp(value,"Raw")
                    app.DeleteDatasetComponentButton.Enable = "off";
                else
                    app.DeleteDatasetComponentButton.Enable = "on";
                end
            end

            % overlay spike pos over probe
            if ~isempty(app.Mainapp.ProbeViewWindowHandle)
                if ~isfield(app.Mainapp.Data,'Spikes')
                    SpikeHandles = findobj(app.Mainapp.ProbeViewWindowHandle.UIAxes, 'Tag', 'SpikePositions');
                    if ~isempty(SpikeHandles)
                        delete(SpikeHandles)
                    end
                else
                    if ~strcmp(app.Mainapp.Plotspikes,"Spikes")
                        SpikeHandles = findobj(app.Mainapp.ProbeViewWindowHandle.UIAxes, 'Tag', 'SpikePositions');
                        if ~isempty(SpikeHandles)
                            delete(SpikeHandles)
                        end
                        
                    end
                end
            end
            app.Mainapp.UIAxes.Box = "off";

            app.Mainapp = Utility_Change_Light_Dark_Mode(app.Mainapp,"MainWindow");

            Organize_Delete_All_Open_Windows(app.Mainapp,0);
        end

        % Button pushed function: ExportDatasetComponentButton
        function ExportDatasetComponentButtonPushed(app, event)
            Utility_Export_Dataset_Components(app.Mainapp.Data,app.DropDown.Value,app.ExportFormatDropDown.Value,app.Mainapp.executableFolder,0);
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            CurrentFormat = app.ExportFormatDropDown.Value;

            % Export restrictions    
            if strcmp(value,"EventRelatedData") || strcmp(value,"PreprocessedEventRelatedData") || strcmp(value,"Time") || strcmp(value,"TimeDownsampled")
                app.ExportFormatDropDown.Items = {};
                app.ExportFormatDropDown.Items{1} = '.mat';
            elseif strcmp(value,"Raw") || strcmp(value,"Preprocessed")
                app.ExportFormatDropDown.Items = {};
                app.ExportFormatDropDown.Items{1} = '.mat';
                app.ExportFormatDropDown.Items{2} = '.dat';
            else
                app.ExportFormatDropDown.Items = {};
                app.ExportFormatDropDown.Items{1} = '.mat';
                app.ExportFormatDropDown.Items{2} = '.txt';
                app.ExportFormatDropDown.Items{3} = '.xlsx';
            end

            for i = 1:length(app.ExportFormatDropDown.Items)
                if strcmp(app.ExportFormatDropDown.Items{i},CurrentFormat)
                    app.ExportFormatDropDown.Value = CurrentFormat;
                end
            end

            % Delete restrictions
            if strcmp(value,"Time") || strcmp(value,"Info") || strcmp(value,"TimeDownsampled") || strcmp(value,"Raw")
                app.DeleteDatasetComponentButton.Enable = "off";
            else
                app.DeleteDatasetComponentButton.Enable = "on";
            end

            if strcmp(value,"Main Window Plot Data")
                app.DeleteDatasetComponentButton.Enable = "off";
                app.ExportDatasetComponentButton.Enable = "off";
            else
                app.ExportDatasetComponentButton.Enable = "on";
            end
        end

        % Close request function: ManageDatasetComponentsWindowUIFigure
        function ManageDatasetComponentsWindowUIFigureCloseRequest(app, event)
            
            try
                app.Mainapp.Manage_Dataset_ComponentsWindow = [];
            end

            delete(app)
            
        end

        % Value changed function: ExportFormatDropDown
        function ExportFormatDropDownValueChanged(app, event)
            value = app.ExportFormatDropDown.Value;
            
        end

        % Button pushed function: ExportComponenttoWorkSpaceButton
        function ExportComponenttoWorkSpaceButtonPushed(app, event)
            Organize_Export_Components_to_WorkSpace(app.Mainapp.Data,app.DropDown.Value,1,app.Mainapp.MainWindowPlotData)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ManageDatasetComponentsWindowUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {294, 294};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ManageDatasetComponentsWindowUIFigure = uifigure('Visible', 'off');
            app.ManageDatasetComponentsWindowUIFigure.Name = 'Manage Dataset Components Window';
            app.ManageDatasetComponentsWindowUIFigure.Icon = 'Logo.png';
            app.ManageDatasetComponentsWindowUIFigure.CloseRequestFcn = createCallbackFcn(app, @ManageDatasetComponentsWindowUIFigureCloseRequest, true);
            app.ManageDatasetComponentsWindowUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.ManageDatasetComponentsWindowUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.ManagementOptionsPanel = uipanel(app.RightPanel);
            app.ManagementOptionsPanel.Title = 'Management Options';
            app.DropDown = uidropdown(app.ManagementOptionsPanel);
            app.DropDown.Items = {};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = {};
            app.DeleteDatasetComponentButton = uibutton(app.ManagementOptionsPanel, 'push');
            app.DeleteDatasetComponentButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteDatasetComponentButtonPushed, true);
            app.DeleteDatasetComponentButton.Text = {'Delete Dataset'; 'Component'};
            app.ExportDatasetComponentButton = uibutton(app.ManagementOptionsPanel, 'push');
            app.ExportDatasetComponentButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDatasetComponentButtonPushed, true);
            app.ExportDatasetComponentButton.Text = 'Export Component to File';
            app.ExportFormatDropDownLabel = uilabel(app.ManagementOptionsPanel);
            app.ExportFormatDropDownLabel.Text = 'Export Format';
            app.ExportFormatDropDown = uidropdown(app.ManagementOptionsPanel);
            app.ExportFormatDropDown.Items = {'.mat', '.txt', '.xlsx'};
            app.ExportFormatDropDown.ValueChangedFcn = createCallbackFcn(app, @ExportFormatDropDownValueChanged, true);
            app.ExportFormatDropDown.Value = '.mat';
            app.ExportFormatDropDownLabel_2 = uilabel(app.ManagementOptionsPanel);
            app.ExportFormatDropDownLabel_2.Text = 'Component';
            app.ManagementOptionsLabel = uilabel(app.ManagementOptionsPanel);
            app.ManagementOptionsLabel.Text = 'Management Options';
            app.ExportComponenttoWorkSpaceButton = uibutton(app.ManagementOptionsPanel, 'push');
            app.ExportComponenttoWorkSpaceButton.ButtonPushedFcn = createCallbackFcn(app, @ExportComponenttoWorkSpaceButtonPushed, true);
            app.ExportComponenttoWorkSpaceButton.Text = {'Export'; 'Component to '; 'WorkSpace'};
            app.TextArea = uitextarea(app.RightPanel);
            app.CloseButton = uibutton(app.RightPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.ManageDatasetComponentsWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Manage_Dataset_Components(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ManageDatasetComponentsWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ManageDatasetComponentsWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ManageDatasetComponentsWindowUIFigure)
        end
    end
end