classdef Select_Event_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SelectEventWindowUIFigure       matlab.ui.Figure
        EventRelatedDataTypeDropDown    matlab.ui.control.DropDown
        EventRelatedDataTypeDropDownLabel  matlab.ui.control.Label
        UseSelectedEventChannelButton   matlab.ui.control.Button
        EventChannelToUseDropDown       matlab.ui.control.DropDown
        EventChannelToUseDropDownLabel  matlab.ui.control.Label
        InformationTextArea             matlab.ui.control.TextArea
        InformationTextAreaLabel        matlab.ui.control.Label
    end

    
    properties (Access = public)
        Data = [] % Description
        SelectedEvent = [];
        SelectedDataType = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Data)
            
            movegui(app.SelectEventWindowUIFigure, 'center');
            
            app = Utility_Change_Light_Dark_Mode(app,"Select_Event_Window");

            app.Data = Data;
            
            app.EventChannelToUseDropDown.Items = {};
            for i = 1:length(Data.Info.EventChannelNames)
                app.EventChannelToUseDropDown.Items{i} = convertStringsToChars(app.Data.Info.EventChannelNames{i});
            end

        end

        % Button pushed function: UseSelectedEventChannelButton
        function UseSelectedEventChannelButtonPushed(app, event)
            app.SelectedEvent = app.EventChannelToUseDropDown.Value;
            app.SelectedDataType = app.EventRelatedDataTypeDropDown.Value;
            uiresume(app.SelectEventWindowUIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SelectEventWindowUIFigure = uifigure('Visible', 'off');
            app.SelectEventWindowUIFigure.Name = 'Select Event Window';
            app.SelectEventWindowUIFigure.Icon = 'Logo.png';
            app.InformationTextAreaLabel = uilabel(app.SelectEventWindowUIFigure);
            app.InformationTextAreaLabel.Text = 'Information';
            app.InformationTextArea = uitextarea(app.SelectEventWindowUIFigure);
            app.InformationTextArea.Value = {'Multiple event channel found. '; 'Please select a single event channel to extract and save event related data for to view in FieldTrip!'};
            app.EventChannelToUseDropDownLabel = uilabel(app.SelectEventWindowUIFigure);
            app.EventChannelToUseDropDownLabel.Text = 'Event Channel To Use';
            app.EventChannelToUseDropDown = uidropdown(app.SelectEventWindowUIFigure);
            app.EventChannelToUseDropDown.Items = {};
            app.EventChannelToUseDropDown.Value = {};
            app.UseSelectedEventChannelButton = uibutton(app.SelectEventWindowUIFigure, 'push');
            app.UseSelectedEventChannelButton.ButtonPushedFcn = createCallbackFcn(app, @UseSelectedEventChannelButtonPushed, true);
            app.UseSelectedEventChannelButton.Text = 'Use Selected Event Channel';
            app.EventRelatedDataTypeDropDownLabel = uilabel(app.SelectEventWindowUIFigure);
            app.EventRelatedDataTypeDropDownLabel.Text = 'Event Related Data Type';
            app.EventRelatedDataTypeDropDown = uidropdown(app.SelectEventWindowUIFigure);
            app.EventRelatedDataTypeDropDown.Items = {'Raw Event Related Data', 'Preprocessed Event Related Data'};
            app.EventRelatedDataTypeDropDown.Value = 'Raw Event Related Data';
            % Show the figure after all components are created
            app.SelectEventWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Select_Event_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SelectEventWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.SelectEventWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SelectEventWindowUIFigure)
        end
    end
end