classdef SpikeTrgAvgAskPrepro < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PreproSTLFPWindowUIFigure   matlab.ui.Figure
        FilterOptionsLabel          matlab.ui.control.Label
        TextArea                    matlab.ui.control.TextArea
        CancelButton                matlab.ui.control.Button
        FilterOptionsPanel          matlab.ui.container.Panel
        ProceedButton               matlab.ui.control.Button
        SaveasnewPreprocessedDatasetDropDown  matlab.ui.control.DropDown
        SaveasnewPreprocessedDatasetDropDownLabel  matlab.ui.control.Label
        FilterOrderEditField        matlab.ui.control.EditField
        FilterOrderEditFieldLabel   matlab.ui.control.Label
        CutoffFrequencyHzEditField  matlab.ui.control.EditField
        CutoffFrequencyHzEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        DatatoUse = []; % Description
        Downsampled = [];
        LowPass = [];
        SpiketrgAvgPrepro = [];
        LowPassFilterSettings = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, LowPassFilterSettings)
            movegui(app.PreproSTLFPWindowUIFigure, 'center');

            app = Utility_Change_Light_Dark_Mode(app,"SpikeTrgAveragePrepro");

            app.LowPassFilterSettings = LowPassFilterSettings;
        end

        % Button pushed function: ProceedButton
        function ProceedButtonPushed(app, event)

            if isempty(app.CutoffFrequencyHzEditField.Value) || isempty(app.FilterOrderEditField.Value)
                msgbox("Either coutoff frequency or filter order field is empty. Please fill in all fields and try again.")
                return;
            end

            app.LowPassFilterSettings.Cutoff = app.CutoffFrequencyHzEditField.Value;
            app.LowPassFilterSettings.FilterOrder = app.FilterOrderEditField.Value;
            app.LowPassFilterSettings.SaveFilter = app.SaveasnewPreprocessedDatasetDropDown.Value;
           
            % Resume execution to return the output
            uiresume(app.PreproSTLFPWindowUIFigure);
           
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app)
        end

        % Value changed function: CutoffFrequencyHzEditField
        function CutoffFrequencyHzEditFieldValueChanged(app, event)
            [app.CutoffFrequencyHzEditField.Value] = Utility_SimpleCheckInputs(app.CutoffFrequencyHzEditField.Value,"One",'220',0,0);
        end

        % Value changed function: FilterOrderEditField
        function FilterOrderEditFieldValueChanged(app, event)
            [app.FilterOrderEditField.Value] = Utility_SimpleCheckInputs(app.FilterOrderEditField.Value,"One",'6',0,0);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PreproSTLFPWindowUIFigure = uifigure('Visible', 'off');
            app.PreproSTLFPWindowUIFigure.Name = 'Prepro ST-LFP Window';
            app.PreproSTLFPWindowUIFigure.Icon = 'Logo.png';
            app.FilterOptionsPanel = uipanel(app.PreproSTLFPWindowUIFigure);
            app.FilterOptionsPanel.Title = 'Filter Options';
            app.CutoffFrequencyHzEditFieldLabel = uilabel(app.FilterOptionsPanel);
            app.CutoffFrequencyHzEditFieldLabel.Text = {'Cutoff Frequency'; '[Hz]'};
            app.CutoffFrequencyHzEditField = uieditfield(app.FilterOptionsPanel, 'text');
            app.CutoffFrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @CutoffFrequencyHzEditFieldValueChanged, true);
            app.CutoffFrequencyHzEditField.Value = '220';
            app.FilterOrderEditFieldLabel = uilabel(app.FilterOptionsPanel);
            app.FilterOrderEditFieldLabel.Text = 'Filter Order';
            app.FilterOrderEditField = uieditfield(app.FilterOptionsPanel, 'text');
            app.FilterOrderEditField.ValueChangedFcn = createCallbackFcn(app, @FilterOrderEditFieldValueChanged, true);
            app.FilterOrderEditField.Value = '6';
            app.SaveasnewPreprocessedDatasetDropDownLabel = uilabel(app.FilterOptionsPanel);
            app.SaveasnewPreprocessedDatasetDropDownLabel.Text = {'Save as new '; 'Preprocessed Dataset'};
            app.SaveasnewPreprocessedDatasetDropDown = uidropdown(app.FilterOptionsPanel);
            app.SaveasnewPreprocessedDatasetDropDown.Items = {'No', 'Yes'};
            app.SaveasnewPreprocessedDatasetDropDown.Value = 'No';
            app.ProceedButton = uibutton(app.FilterOptionsPanel, 'push');
            app.ProceedButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedButtonPushed, true);
            app.ProceedButton.Text = 'Proceed';
            app.CancelButton = uibutton(app.PreproSTLFPWindowUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.TextArea = uitextarea(app.PreproSTLFPWindowUIFigure);
            app.TextArea.Value = {'Warning:'; ''; 'Data to extract spike triggered LFP from is not low pass filtered.'; 'Please confirm filter parameters on the right (or change them beforehand) by clicking on proceed and continoue with applying a low pass filter. Selecting ''yes'' in the dropdown menu will save low pass filtered data as new preprocessed dataset.'; ''; 'Standard Filter Settings:'; ''; 'Filter Order: 6'; 'Cutoff Frequency: 220Hz'};
            app.FilterOptionsLabel = uilabel(app.PreproSTLFPWindowUIFigure);
            app.FilterOptionsLabel.Text = 'Filter Options';
            % Show the figure after all components are created
            app.PreproSTLFPWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpikeTrgAvgAskPrepro(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.PreproSTLFPWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.PreproSTLFPWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PreproSTLFPWindowUIFigure)
        end
    end
end