classdef Extract_Data_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ExtractDataWindowUIFigure    matlab.ui.Figure
        Menu_2                       matlab.ui.container.Menu
        TrajectoryExplorerMenu       matlab.ui.container.Menu
        StartTrajectoryExplorerMenu  matlab.ui.container.Menu
        LoadSavedTrajectoryMenu      matlab.ui.container.Menu
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        ExtractDataButton            matlab.ui.control.Button
        ExtractionOptionsPanel       matlab.ui.container.Panel
        KeepConsoleOpen_3            matlab.ui.control.CheckBox
        FormattoSaveProbeinLabel     matlab.ui.control.Label
        Save_Probe_SpikeInterface    matlab.ui.control.DropDown
        Save_Probe_Kilosort          matlab.ui.control.CheckBox
        AdditionalAmplificationFactorEditField_3  matlab.ui.control.EditField
        AmplificationFactorLabel_3   matlab.ui.control.Label
        AdditionalAmplificationFactorEditField_2  matlab.ui.control.EditField
        AmplificationFactorLabel_2   matlab.ui.control.Label
        FormatToSaveandReadintoMatlabLabel  matlab.ui.control.Label
        FormatToSaveandReadintoMatlabDropDown  matlab.ui.control.DropDown
        DataExtractionLibraryLabel   matlab.ui.control.Label
        RecordingSystemDropDown_2    matlab.ui.control.DropDown
        ExtractionOptionsLabel       matlab.ui.control.Label
        KeepConsoleOpen              matlab.ui.control.CheckBox
        KeepConsoleOpen_2            matlab.ui.control.CheckBox
        AddProbeInformationButton    matlab.ui.control.Button
        FileTypeDropDown             matlab.ui.control.DropDown
        FileTypeLabel                matlab.ui.control.Label
        AutoDetectedRecordingSystemLabel  matlab.ui.control.Label
        RecordingSystemDropDown      matlab.ui.control.DropDown
        SelectDataFolderButton       matlab.ui.control.Button
        AdditionalAmplificationFactorEditField  matlab.ui.control.EditField
        AmplificationFactorLabel     matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        CloseButton                  matlab.ui.control.Button
        FolderContentsLabel          matlab.ui.control.Label
        TextArea_3                   matlab.ui.control.TextArea
        InformationLabel             matlab.ui.control.Label
        TextArea                     matlab.ui.control.TextArea
        TextArea_4                   matlab.ui.control.TextArea
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    % Define app properties shared across all app functions
    properties (Access = public)
        ProbeInfoandPath = []; % Info structure holding all relevant informations for this window

        ProbeTrajectoryInfo = [];
        
        TimeAndChannelToExtract = [];
        
        CorrectDCOffset = 0;

        ProbeLayoutWindow = []; % when opened here this gets populated
        Mainapp = [];
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            % Moce this window in the middle of thescreen
            movegui(app.ExtractDataWindowUIFigure, 'center');            %% Predefine variables for the app to work
            
            app.Mainapp = Mainapp; % Save Mainapp in this app as object; Data can be accesed via app.Mainapp.Data

            app = Utility_Change_Light_Dark_Mode(app,"ExtractDataWindow");

            app.KeepConsoleOpen.Enable = "off";
            app.KeepConsoleOpen.Visible = 0;
            app.KeepConsoleOpen_2.Enable = "off";
            app.KeepConsoleOpen_2.Visible = 0;

            app.Save_Probe_Kilosort.Enable = "off";
            app.Save_Probe_Kilosort.Visible = "off";
            app.Save_Probe_SpikeInterface.Enable = "off";
            app.Save_Probe_SpikeInterface.Visible = "off";
            app.FormattoSaveProbeinLabel.Visible = "off";
            app.KeepConsoleOpen_3.Enable = "off";
            app.KeepConsoleOpen_3.Visible = "off";

            app.TimeAndChannelToExtract.TimeToExtract = [];
            app.TimeAndChannelToExtract.ChannelToExtract = [];

            app.Mainapp.CheckSuccesful_Extraction = 0; % 0 if extraction wasnt succesfully yet

            app.ExtractDataButton.Enable = "on"; % Folder has to be selected for the extraction button to become enabled
            MatlabTextNoFolder = ["No folder or file selected to pass to Matlab:";"";"Please select a folder containing your recording data. Supported are formats recorded with the Open Ephys GUI, Intan RHX data acquisition software (and legacy RHD software), Spike2 as well as Neuralynx Cheetah and Tucker Davis (TDT) recordings. This includes binary, .nwb and Open Ephys data formats from the Open Ephys GUI recorded with Neuropixels, Open Ephys and Intan acquisition boards; .dat and .rhd files from the Intan RHX and RHD software; .smrx files from Spike2, .ncs (and accompanying) Neuralynx files from Chetaah, SpikeGLX .ap.bin/.lp.bin NP recording files and .sev (and accompanying) files for TDT recordings. Folder contents, file types and recording system will be shown automatically if folder contains one of the supported file types.";"Please make sure that the standard folder structure created by the recording software is NOT changed and the recording folder only contains files and folder created by the recording software OR this GUI!";"";"After selecting a folder, specify probe information. Required fields are channel number and channelspacing to specify the probe geometry. Active channel specify the amount and identity of probe channels you recorded from. The number of channels in your recording has to be the same as the number of elements in your active channel selection!";"After setting the probe information you can start the data extraction.";"";"Note: For Open Ephys data the expected folder structure is Date/Record Node/experiment/recordings/.... Multiple recordings within the same sessions can be concatonated or loaded individually. Please make sure only one recording session is present in the selected file, independent of the file format and recording system!"];
            
            app.TextArea.Value = ["Raw recordings can be extracted with three different libraries - the NeuroMod internal library, the NeuralEnsemble Neo library and the SpikeInterface library, each supporting some of the same, but also different recording formats. When you select a folder, NeuroMod automatically determines the recording format, available libraries and populates the fields in this window for you to select. For more information about NEO and SpikeInterface, see the README file or select either of the libraries in the 'Data Extraction Library' dropdown and see the info appearing in this text area.";"";"NeuroMod internal format:";"";MatlabTextNoFolder];
            app.TextArea_3.Value = "Please select a folder";
            pause(0.2);

            app.RecordingSystemDropDown_2.Items{1} = 'NeuroMod Matlab';
            app.RecordingSystemDropDown_2.Items{2} = 'NeuralEnsemble NEO Python Library';
            app.RecordingSystemDropDown_2.Items{3} = 'SpikeInterface Python Library';

            %% Dynamically add menu points based on savefile found in standard folder
  
            % Create parent menu
            parentMenu = uimenu(app.ExtractDataWindowUIFigure, 'Text', '   Load Saved Probe Information   ');
            
            % Create the secondary menu 
            filesMenu = uimenu(parentMenu, 'Text', 'Files in Standard Directory');

            % Create the second secondary menu 
            loadMenu = uimenu(parentMenu, 'Text', 'Manually Select File');

            % Extract filenames from standard folder
            DefaultFolder = strcat(app.Mainapp.executableFolder,'\Probe Layouts\Saved Probe Layouts\');
            
            app.ProbeInfoandPath.selectedFolder = [];

            if ~exist(DefaultFolder,"dir")
                disp(strcat("Standard Folder",DefaultFolder ," was not found. Please load probe information manually. No quick load from menu available!"));
            else
                [stringArray] = Utility_Extract_Contents_of_Folder(DefaultFolder);
                % exclude emoty and non - .mat parts
                stringArray = stringArray(stringArray ~= "");
                fileNames = stringArray(contains(stringArray,".mat"))';

                for i = 1:length(fileNames)
                    uimenu(filesMenu, 'Text', fileNames(i), 'MenuSelectedFcn', @(src,event) Menu_Extract_Data_Callback(app, fileNames(i), DefaultFolder , "Extract Data"));
                end
                
                uimenu(loadMenu, 'Text', 'Manually Select File', 'MenuSelectedFcn', @(src,event) Menu_Extract_Data_Load_Manually_Callback(app, "Extract Data"));
                
            end

        end

        % Button pushed function: ExtractDataButton
        function ExtractDataButtonPushed(app, event)
            if ~strcmp(app.RecordingSystemDropDown_2.Value,"SpikeInterface Python Library")
                if ~isfield(app.ProbeInfoandPath,'ChannelSpacing')
                    msgbox("Error: Please first set a Channelspacing using the 'Probe Information' Button.");
                    return;
                else
                    if isempty(app.ProbeInfoandPath.ChannelSpacing)
                        msgbox("Error: Please first set a Channelspacing using the 'Probe Information' Button.");
                        return;
                    end
                end
                if isempty(app.ProbeInfoandPath.selectedFolder)
                    msgbox("Error: Please select a folder first.");
                    return;
                end
    
                if ~isfield(app.ProbeInfoandPath,'NrChannel')
                    msgbox("Error: Please first set a channel number using the 'Probe Information' Button.");
                    return;
                else
                    if isempty(app.ProbeInfoandPath.NrChannel)
                        msgbox("Error: Please first set a channel number using the 'Probe Information' Button.");
                        return;
                    end
                end
                if ~isfield(app.ProbeInfoandPath,'ActiveChannel')
                    msgbox("Error: Please first set active channel using the 'Probe Information' Button.");
                    return;
                else
                    if isempty(app.ProbeInfoandPath.NrChannel)
                        msgbox("Error: Please first set active channel using the 'Probe Information' Button.");
                        return;
                    end
                end
            end

            app.TextArea_3.Value = "Loading Data, please wait until this window closes.";
            pause(0.01);
            
            %% Extract Data
            tic
            
            if ~isempty(app.ProbeInfoandPath.selectedFolder)
                
                app.TimeAndChannelToExtract.ChannelToExtract = app.AdditionalAmplificationFactorEditField_2.Value;
                app.TimeAndChannelToExtract.TimeToExtract = app.AdditionalAmplificationFactorEditField_3.Value;
                
                %% Prepare GUI
                % Function to reset Main Window when new Data is loaded
                % i.e resetting plot, TimeRange of plot, channel
                % selection...AND to delete old data structure if already present
                [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Initial");
                
                %% Extract Data
                if strcmp(app.RecordingSystemDropDown_2.Value,"NeuralEnsemble NEO Python Library") % If NEO data extraction

                    % first chekc if its a neuropixels 1.0 recording. If
                    % yes, ask if user wants LFP or AP signal

                    [IsNP1,DataPartToextract] = Manage_Dataset_Check_NEO_NP1_Recording(app.Mainapp.Data,app.ProbeInfoandPath.selectedFolder);
                    
                    % Open extra window, get more settings, start NEO python
                    [Success] = Manage_Dataset_Module_Start_Neo(app.ProbeInfoandPath.selectedFolder,app.Mainapp.executableFolder,app.KeepConsoleOpen_2.Value,app.KeepConsoleOpen.Value,app.RecordingSystemDropDown.Value,app.FormatToSaveandReadintoMatlabDropDown.Value,IsNP1,DataPartToextract,app.AdditionalAmplificationFactorEditField_3.Value,app.AdditionalAmplificationFactorEditField_2.Value);
                    if Success == 0
                        return;
                    end

                    % Load saved results from NEO
                    [Data,SampleRate,HeaderInfo,RecordingType,Time,texttoshow] = Manage_Dataset_Load_NEO_RawData_Save_Files(app.ProbeInfoandPath.selectedFolder,app.RecordingSystemDropDown.Value,app.FormatToSaveandReadintoMatlabDropDown.Value,app.TimeAndChannelToExtract);
                    
                    if isempty(Data)
                        app.TextArea.Value = texttoshow;
                        msgbox("Extracting Data Failed! See above for more information.");
                        app.TextArea_3.Value = "Extracting Data Failed! See above for more information.";
                        return;
                    else
                        app.TextArea.Value = texttoshow;
                    end

                elseif strcmp(app.RecordingSystemDropDown_2.Value,"SpikeInterface Python Library") % If SpikeInterface data extraction
                    
                    % Open extra window, get more settings, start SpikeInterface python
                    [Success] = Manage_Dataset_Module_Start_SpikeInterface(app.ProbeInfoandPath.selectedFolder,app.Mainapp.executableFolder,app.KeepConsoleOpen_2.Value,app.KeepConsoleOpen.Value,app.RecordingSystemDropDown.Value,app.FormatToSaveandReadintoMatlabDropDown.Value,app.Save_Probe_Kilosort.Value,app.Save_Probe_SpikeInterface.Value,app.AdditionalAmplificationFactorEditField_3.Value,app.AdditionalAmplificationFactorEditField_2.Value,app.CorrectDCOffset);
                    if Success == 0
                        return;
                    end

                    % Load saved results from NEO

                    [Data,SampleRate,HeaderInfo,RecordingType,Time,texttoshow] = Manage_Dataset_Load_SpikeInterface_RawData_Save_Files(app.ProbeInfoandPath.selectedFolder,app.TimeAndChannelToExtract);
                    
                    if isempty(Data)
                        app.TextArea.Value = texttoshow;
                        msgbox("Extracting Data Failed! See above for more information.");
                        app.TextArea_3.Value = "Extracting Data Failed! See above for more information.";
                        return;
                    else
                        app.TextArea.Value = texttoshow;
                    end

                else % If GUI intern data extraction
                    [Data,HeaderInfo,SampleRate,RecordingType,Time] = Manage_Dataset_Module_Extract_Raw_Recording_Main(app.RecordingSystemDropDown.Value,app.FileTypeDropDown.Value,app.ProbeInfoandPath.selectedFolder,app.TextArea,app.Mainapp.executableFolder,app.AdditionalAmplificationFactorEditField.Value,app.ProbeInfoandPath.ActiveChannel,app.ProbeInfoandPath.Channelorder,app.TimeAndChannelToExtract);
                end

                if isempty(Data)
                    app.TextArea.Value = "No Amplifier Channel Data found! Data Extraction cannot be finished.";
                    app.TextArea_3.Value = app.ProbeInfoandPath.selectedFolder;
                    pause(0.1);
                    return;
                end

                %% Apply/save Header Infos

                % Use some header infos and the data structure to define all necessary variables for this toolbox
                % Variable definitions necessary:
                % app.Data = Data.Raw and or Data.Preprocessed, depending on what is available;
                % app.Data.Time = Time;
                % app.Data.Info = HeaderInfo;
                % app.Data.Info.num_data_points = size(app.Data.Raw,2);
                % app.Data.Info.NrChannel = size(app.Data.Raw,1);
                % app.Data.Info.Data_Path = SelectedFolder;
                % app.Data.Info.NativeSamplingRate = SampleRate;
                % app.Data.Info.RecordingType = RecordingType;
                
                [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"VariableDefinition",Data,HeaderInfo,SampleRate,app.ProbeInfoandPath.selectedFolder,RecordingType,0,Time,app.ProbeInfoandPath);
        	    
                if isempty(app.Mainapp.Data)
                    app.TextArea.Value = 'Recording could not be extracted.';
                    return;
                end

                if strcmp(app.RecordingSystemDropDown_2.Value,"SpikeInterface Python Library")
                    % [NewChannelOrder] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.Mainapp.Data.Info.ProbeInfo.MEAChannelOrder,'MainPlot');
                    % [app.Mainapp.Data] = Manage_Dataset_Module_Apply_ChannelOrder(app.Mainapp.Data,NewChannelOrder);
                else
                    [app.Mainapp.Data] = Manage_Dataset_Module_Apply_ChannelOrder(app.Mainapp.Data,app.ProbeInfoandPath.Channelorder);
                end
         
                if app.Mainapp.Data.Info.ProbeInfo.FlipLoadedData == 1
                    [app.Mainapp.Data.Raw] = Manage_Dataset_Module_Apply_DataFlip(app.Mainapp.Data.Raw);
                end
             
                %% 
            else
                % If the user hasnt selected a folder yet, display that and
                % return
                stringtoshow = "Error. No folder selected";
                f = msgbox(stringtoshow);
                return;
            end

            %% Initialize Infos for Main Window based on extracted and saved variables (previous call of this function)
            [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Extracting");

            toc
            %% Get all necessary Infos from Main Window to plot, set time scale based on time window AND plot data
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            
            %% Plot twice! Since This is setting the plotobject again, but in the reversed, 'proper' order. Otherwise channel name display when clickin on data plot will show Ch1 for Ch64 without updating the plot
            Utility_Initialize_Clicks_Plots(app.Mainapp,"Static");
            
            %% Open Probe View
            app.Mainapp.ProbeViewWindowHandle = Probe_View_Window(app.Mainapp,'MainWindow');

            [app.Mainapp] = Utility_Set_ToolTips(app.Mainapp,app.Mainapp.ShowToolTipsSetting,"ProbeViewMainWindow");
            
            app.Mainapp = Utility_Change_Light_Dark_Mode(app.Mainapp,"MainWindow");

            % Close this window when Extraction succesfull
            close(app.ExtractDataWindowUIFigure);

        end

        % Button pushed function: SelectDataFolderButton
        function SelectDataFolderButtonPushed(app, event)
            app.ProbeInfoandPath.selectedFolder = [];
            app.RecordingSystemDropDown_2.Items = {};

            PathToOpen = strcat(app.Mainapp.executableFolder,'\Recording Data\Raw Recordings\');
            
            [app.ProbeInfoandPath,ProbeInfoText,FolderContentsText] = Manage_Dataset_Module_Show_ProbeInfo_and_Path(app,app.ProbeInfoandPath,"FolderSelection",PathToOpen,0,app.ProbeInfoandPath.selectedFolder,app.RecordingSystemDropDown_2.Value,app.RecordingSystemDropDown.Value,0);
            
            if isempty(app.ProbeInfoandPath.selectedFolder)
                app.RecordingSystemDropDown_2.Items{1} = 'NeuroMod Matlab';
                app.RecordingSystemDropDown_2.Items{2} = 'NeuralEnsemble NEO Python Library';
                app.RecordingSystemDropDown_2.Items{3} = 'SpikeInterface Python Library';
                app.RecordingSystemDropDown_2.Value = app.RecordingSystemDropDown_2.Items{1};

                app.RecordingSystemDropDown.Items = {};
                app.FileTypeDropDown.Items = {};
            end

            if strcmp(app.RecordingSystemDropDown_2.Value,"SpikeInterface Python Library")
               app.AddProbeInformationButton.Enable = "off";
            else
               app.AddProbeInformationButton.Enable = "on";
            end

            if strcmp(app.RecordingSystemDropDown_2.Value,"NeuralEnsemble NEO Python Library") || strcmp(app.RecordingSystemDropDown_2.Value,"SpikeInterface Python Library")
                app.AutoDetectedRecordingSystemLabel.Text = 'Select Recording System';
            end

            if strcmp(app.ProbeTrajectoryInfo,"Loaded")
                ProbeInfoText(end+1) = "";
                ProbeInfoText(end+2) = " Succesfully loaded Probe Trajectory.";
            end

            if ~isempty(ProbeInfoText)
                app.TextArea.Value = ProbeInfoText;
            else
                app.TextArea.Value = '';
            end

            if ~isempty(FolderContentsText)
                app.TextArea_3.Value = FolderContentsText;
            else
                app.TextArea_3.Value = '';
            end
            
            if ~isempty(app.RecordingSystemDropDown_2.Items)
                app.RecordingSystemDropDown_2.Value = app.RecordingSystemDropDown_2.Items{1};
            else
                app.RecordingSystemDropDown.Items = {};
            end
            
            app.AdditionalAmplificationFactorEditField_2.Enable = "on";
            app.AdditionalAmplificationFactorEditField_3.Enable = "on";

            if strcmp(app.RecordingSystemDropDown_2.Value,"NeuralEnsemble NEO Python Library")
                app.AddProbeInformationButton.Enable = "on";
                app.AddProbeInformationButton.Visible = "on";
                app.Save_Probe_Kilosort.Enable = "off";
                app.Save_Probe_Kilosort.Visible = "off";
                app.Save_Probe_SpikeInterface.Enable = "off";
                app.Save_Probe_SpikeInterface.Visible = "off";
                app.FormattoSaveProbeinLabel.Visible = "off";
                app.CorrectDCOffset = 0;

                app.FormatToSaveandReadintoMatlabLabel.Enable = "on";
                app.FormatToSaveandReadintoMatlabLabel.Visible = "on";

                app.FormatToSaveandReadintoMatlabDropDown.Enable = "on";
                app.FormatToSaveandReadintoMatlabDropDown.Visible = "on";
                app.FormatToSaveandReadintoMatlabDropDown.Items = {};
                app.FormatToSaveandReadintoMatlabDropDown.Items{1} = 'NEO Format to .mat Conversion';
                app.FormatToSaveandReadintoMatlabDropDown.Items{2} = 'Custom files (.dat,.mat)';
                app.FormatToSaveandReadintoMatlabDropDown.Value = app.FormatToSaveandReadintoMatlabDropDown.Items{2};

                if ~isempty(app.RecordingSystemDropDown.Items)
                    app.RecordingSystemDropDown.Items{2} = 'NEO Format Autodetection';
                end

                app.FileTypeDropDown.Enable = "off";
                app.KeepConsoleOpen.Enable = "on";
                app.KeepConsoleOpen.Visible = 1;
                app.KeepConsoleOpen_2.Enable = "on";
                app.KeepConsoleOpen_2.Visible = 1;
                app.KeepConsoleOpen_3.Enable = "off";
                app.KeepConsoleOpen_3.Visible = "off";

                app.FileTypeDropDown.Enable = "off";
                app.FileTypeDropDown.Visible = 0;

                app.AutoDetectedRecordingSystemLabel.Text = 'Select Recording System';
                app.FileTypeLabel.Enable = "off";
                app.FileTypeLabel.Visible = 0;

            elseif strcmp(app.RecordingSystemDropDown_2.Value,"SpikeInterface Python Library")
                
                app.AddProbeInformationButton.Enable = "off";
                app.AddProbeInformationButton.Visible = "off";
                app.Save_Probe_Kilosort.Enable = "on";
                app.Save_Probe_Kilosort.Visible = "on";
                app.Save_Probe_SpikeInterface.Enable = "on";
                app.Save_Probe_SpikeInterface.Visible = "on";
                app.FormattoSaveProbeinLabel.Visible = "on";
                
                app.KeepConsoleOpen_3.Enable = "on";
                app.KeepConsoleOpen_3.Visible = "on";
                
                app.FormatToSaveandReadintoMatlabLabel.Enable = "off";
                app.FormatToSaveandReadintoMatlabLabel.Visible = "off";
                app.FormatToSaveandReadintoMatlabDropDown.Enable = "off";
                app.FormatToSaveandReadintoMatlabDropDown.Visible = "off";

                app.FormatToSaveandReadintoMatlabDropDown.Items = {};
                app.FormatToSaveandReadintoMatlabDropDown.Items{1} = 'Custom files (.dat,.mat)';
                
                app.FileTypeDropDown.Enable = "off";
                app.KeepConsoleOpen.Enable = "on";
                app.KeepConsoleOpen.Visible = 1;
                app.KeepConsoleOpen_2.Enable = "on";
                app.KeepConsoleOpen_2.Visible = 1;

                app.FileTypeDropDown.Enable = "off";
                app.FileTypeDropDown.Visible = 0;

                app.AutoDetectedRecordingSystemLabel.Text = 'Select Recording System';
                app.FileTypeLabel.Enable = "off";
                app.FileTypeLabel.Visible = 0;
                app.AdditionalAmplificationFactorEditField_2.Value = "All";
                app.AdditionalAmplificationFactorEditField_2.Enable = "off";

            else
                app.AddProbeInformationButton.Enable = "on";
                app.AddProbeInformationButton.Visible = "on";
                
                app.CorrectDCOffset = 0;
                
                app.Save_Probe_Kilosort.Enable = "off";
                app.Save_Probe_Kilosort.Visible = "off";
                app.Save_Probe_SpikeInterface.Enable = "off";
                app.Save_Probe_SpikeInterface.Visible = "off";
                app.FormattoSaveProbeinLabel.Visible = "off";
                
                app.FormatToSaveandReadintoMatlabLabel.Enable = "on";
                app.FormatToSaveandReadintoMatlabLabel.Visible = "on";

                app.FormatToSaveandReadintoMatlabDropDown.Enable = "on";
                app.FormatToSaveandReadintoMatlabDropDown.Visible = "on";
                app.FormatToSaveandReadintoMatlabDropDown.Items = {};
                app.FileTypeDropDown.Enable = "on";
                app.KeepConsoleOpen.Enable = "off";
                app.KeepConsoleOpen.Visible = 0;
                app.KeepConsoleOpen_2.Enable = "off";
                app.KeepConsoleOpen_2.Visible = 0;
                app.KeepConsoleOpen_3.Enable = "off";
                app.KeepConsoleOpen_3.Visible = "off";

                app.FileTypeDropDown.Enable = "on";
                app.FileTypeDropDown.Visible = 1;
                app.RecordingSystemDropDown.Enable = "on";
                app.RecordingSystemDropDown.Visible = 1;

                app.AutoDetectedRecordingSystemLabel.Text = 'Auto Detected Recording System';
                app.AutoDetectedRecordingSystemLabel.Visible = 1;
                app.FileTypeLabel.Enable = "on";
                app.FileTypeLabel.Visible = 1;

                app.KeepConsoleOpen.Value = 0;
                app.KeepConsoleOpen_2.Value = 0;

            end

            if app.KeepConsoleOpen_2.Value == 1
                app.KeepConsoleOpen.Value = 0;
                app.KeepConsoleOpen.Enable = "off";
            else
                app.KeepConsoleOpen.Enable = "on";
            end

            % if strcmp(app.RecordingSystemDropDown_2.Value,"NeuralEnsemble NEO Python Library")
            %     app.FormatToSaveandReadintoMatlabDropDown.Enable = "on";
            % else
            %     app.FormatToSaveandReadintoMatlabDropDown.Enable = "off";
            % end
        end

        % Button pushed function: AddProbeInformationButton
        function AddProbeInformationButtonPushed(app, event)
            
            app.ProbeLayoutWindow = Probe_Layout_Window(app.Mainapp,app);
            
            [app.Mainapp] = Utility_Set_ToolTips(app.Mainapp,app.Mainapp.ShowToolTipsSetting,"SetProbeInfoWindow");

        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            try
                if ~isempty(app.ProbeLayoutWindow)
                    delete(app.ProbeLayoutWindow);
                end
    
                app.Mainapp.ExtractDataWindow = [];
    
                delete(app);
            catch ME
                delete(app);
            end
        end

        % Value changed function: RecordingSystemDropDown
        function RecordingSystemDropDownValueChanged(app, event)
          
        end

        % Value changed function: FileTypeDropDown
        function FileTypeDropDownValueChanged(app, event)
           
            
        end

        % Value changed function: AdditionalAmplificationFactorEditField
        function AdditionalAmplificationFactorEditFieldValueChanged(app, event)
            
        end

        % Menu selected function: StartTrajectoryExplorerMenu
        function StartTrajectoryExplorerMenuSelected(app, event)
            neuropixels_trajectory_explorer;
        end

        % Menu selected function: LoadSavedTrajectoryMenu
        function LoadSavedTrajectoryMenuSelected(app, event)
            PathToSave = strcat(app.Mainapp.executableFolder,'\Probe Layouts\Trajectories\');
            
            if isfolder(PathToSave)
                [fileName, filePath] = uigetfile(fullfile(PathToSave,'*.mat*'), 'Select a File to Load');
            else
                [fileName, filePath] = uigetfile('*.mat*', 'Select a File to Load');
            end
            
            % Check if the user selected a file or canceled the dialog
            if isequal(fileName, 0)
                disp('File selection canceled.');
            else
               
                fullFileName = fullfile(filePath, fileName);

                % Load the file (assuming it's a .mat file)
                LoadedProbeTrajectoryInfo = load(fullFileName);
                Areas = LoadedProbeTrajectoryInfo.probe_areas; 

                app.ProbeInfoandPath.ProbeTrajectoryInfo.AreaNamesLong = Areas{1}.name;
                app.ProbeInfoandPath.ProbeTrajectoryInfo.AreaNamesShort = Areas{1}.acronym;
                
                app.ProbeInfoandPath.ProbeTrajectoryInfo.AreaTipDistance = Areas{1}.tip_distance;
                
                disp('Probe Trajectory Info successfully loaded.');
                
                app.ProbeTrajectoryInfo = "Loaded";
                if length(app.TextArea.Value)==1
                    TextAreaContents = app.TextArea.Value{1};
                    TextAreaContents = strcat(TextAreaContents," "," Succesfully loaded Probe Trajectory.");
                else
                    TextAreaContents = app.TextArea.Value;
                    TextAreaContents{end+1} = ' Succesfully loaded Probe Trajectory.';
                end

                app.TextArea.Value = TextAreaContents;
            end

        end

        % Close request function: ExtractDataWindowUIFigure
        function ExtractDataWindowUIFigureCloseRequest(app, event)
            try
                if ~isempty(app.ProbeLayoutWindow)
                    delete(app.ProbeLayoutWindow);
                end
    
                app.Mainapp.ExtractDataWindow = [];
    
                delete(app);

            catch ME
                delete(app);
            end
            
        end

        % Value changed function: RecordingSystemDropDown_2
        function RecordingSystemDropDown_2ValueChanged(app, event)
            NeoTextNoFolder = ["No folder or file selected to pass to NEO:";"";"Using the NeuralEnsemble NEO library to extract data from a raw recording. If using NEO for the first time, you are asked for a path to the python.exe in the environment you installed the package in. Please refer to the README how to install NEO and select the python.exe.";"";"First select a folder or file containing your recording data. Currently supported formats that can be loaded into NeuroMod using NEO include Neuralynx, Plexon and Open Ephys recordings.";"";"**NOTE**";"Please make sure that the standard folder structure created by the recording software is NOT changed and the recording folder only contains files and folder created by the recording software!";"";"After selecting a folder, it will be auto-searched for a suitable recording format. If it found one compatible with NEO, you can select it as the data extraction library to use. The 'Select Recording System' dropdown allows you to use NEO's format autodetection or directly use the format detection already done in Matlab (switch in case of incompatibilities).";"";"After Neo extracted data, it is saved in a newly created folder next to the recording folder to be loaded into NeuroMod (parent folder of recording folder/{Recording name} NEO SaveFile). You can determine the format it is saved in (for further personal use) using the 'Format to Save and Read into Matlab' dropdown menu. Neo format .mat files are created using a function of NEO directly, while 'custom format' saves a .dat file for channel data and .mat file for meta – and event data. The next time you want to load a recording you already extracted, select the 'Load Already Saved Files' checkbox to skip data extraction with NEO and load the saved file immediately.";"";"After selecting a folder, specify probe information. Required fields are channel number and channel spacing to specify the probe geometry. Active channel specify the amount and identity of probe channels you recorded from. The number of channels in your recording has to be the same as the number of elements in your active channel selection!";"";"After setting the probe information you can start the data extraction.";"This opens a python console prompt window showing the progress of data extraction. If something should not work, activate the 'Keep Console Open' checkbox to see potential error messages (and make sure to press enter in the console after the python script finished!). If the folder should NOT contain a suitable recording, you are being informed about it here."];
            NeoTextWithFolder = ["Using the NeuralEnsemble Neo library to extract data from a raw recording. If using NEO for the first time, you are asked for a path to the python.exe in the environment you installed Neo in. Please refer to the README how to install NEO and select the python.exe.";"";"**NOTE**";"Please make sure that the standard folder structure created by the recording software is NOT changed and the recording folder only contains files and folder created by the recording software!";"";"After selecting a folder, it will be auto-searched for a suitable recording format. If it found one compatible with NEO, you can select it as the data extraction library to use. The 'Select Recording System' dropdown allows you to use NEO's format autodetection or directly use the format detection already done in Matlab (switch in case of incompatibilities).";"";"After Neo extracted data, it is saved in a newly created folder next to the recording folder to be loaded into NeuroMod (parent folder of recording folder/{Recording name} NEO SaveFile). You can determine the format it is saved in (for further personal use) using the 'Format to Save and Read into Matlab' dropdown menu. Neo format .mat files are created using a function of NEO directly, while 'custom format' saves a .dat file for channel data and .mat file for meta – and event data. The next time you want to load a recording you already extracted, select the 'Load Already Saved Files' checkbox to skip data extraction with NEO and load the saved file immediately.";"";"After selecting a folder, specify probe information. Required fields are channel number and channel spacing to specify the probe geometry. Active channel specify the amount and identity of probe channels you recorded from. The number of channels in your recording has to be the same as the number of elements in your active channel selection!";"";"After setting the probe information you can start the data extraction.";"This opens a python console prompt window showing the progress of data extraction. If something should not work, activate the 'Keep Console Open' checkbox to see potential error messages (and make sure to press enter in the console after the python script finished!). If the folder should NOT contain a suitable recording, you are being informed about it here."];
 
            MatlabTextNoFolder = ["No folder or file selected to pass to Matlab:";"";"Please select a folder containing your recording data. Supported are formats recorded with the Open Ephys GUI, Intan RHX data acquisition software (and legacy RHD software), Spike2 as well as Neuralynx Cheetah and Tucker Davis (TDT) recordings. This includes binary, .nwb and Open Ephys data formats from the Open Ephys GUI recorded with Neuropixels, Open Ephys and Intan acquisition boards; .dat and .rhd files from the Intan RHX and RHD software; .smrx files from Spike2, .ncs (and accompanying) Neuralynx files from Chetaah, SpikeGLX .ap.bin/.lp.bin NP recording files and .sev (and accompanying) files for TDT recordings. Folder contents, file types and recording system will be shown automatically if folder contains one of the supported file types.";"Please make sure that the standard folder structure created by the recording software is NOT changed and the recording folder only contains files and folder created by the recording software OR this GUI!";"";"After selecting a folder, specify probe information. Required fields are channel number and channelspacing to specify the probe geometry. Active channel specify the amount and identity of probe channels you recorded from. The number of channels in your recording has to be the same as the number of elements in your active channel selection!";"After setting the probe information you can start the data extraction.";"";"Note: For Open Ephys data the expected folder structure is Date/Record Node/experiment/recordings/.... Multiple recordings within the same sessions can be concatonated or loaded individually. Please make sure only one recording session is present in the selected file, independent of the file format and recording system!"];
            MatlabTextWithFolder = ["Folder contents, file types and recording system will be shown automatically if folder contains one of the supported file types.";"Please make sure that the standard folder structure created by the recording software is NOT changed and the recording folder only contains files and folder created by the recording software OR this GUI!";"";"After selecting a folder, specify probe information. Required fields are channel number and channelspacing to specify the probe geometry. Active channel specify the amount and identity of probe channels you recorded from. The number of channels in your recording has to be the same as the number of elements in your active channel selection!";"After setting the probe information you can start the data extraction.";"";"Note: For Open Ephys data the expected folder structure is Date/Record Node/experiment/recordings/.... Multiple recordings within the same sessions can be concatonated or loaded individually. Please make sure only one recording session is present in the selected file, independent of the file format and recording system!"];
            
            SITextNoFolder = ["No folder or file selected to pass to SpikeInterface:";"";"Using the SpikeInterface library to extract data from a raw recording. If using SpikeInterface for the first time, you are asked for a path to the python.exe in the environment you installed the package in. Please refer to the README how to install SpikeInterface and select the python.exe.";"";"First select a folder or file containing your recording data. Currently supported formats that can be loaded into NeuroMod using SpikeInterface include Maxwell MEA .h5 recordings.";"";"You do not need to specify a probe design, it is automatically created based on the recording meta data. Probe design can be saved as .mat or .prb file to use in external Kilosort GUI and/or your own SpikeInterface pipeline.";"";"**NOTE**";"Please make sure that the standard folder structure created by the recording software is NOT changed and the recording folder only contains files and folder created by the recording software!";"";"After selecting a folder, it will be auto-searched for a suitable recording format. If it found one compatible with SpikeInterface, you can select it as the data extraction library to use.";"";"After SpikeInterface extracted data, it is saved in a newly created folder next to the recording folder to be loaded into NeuroMod (parent folder of recording folder/{Recording name} SpikeInterface SaveFile). You can determine the format it is saved in (for further personal use) using the 'Format to Save and Read into Matlab' dropdown menu. 'Custom format' saves a .dat file for channel data and .mat file for meta – and event data. The next time you want to load a recording you already extracted, select the 'Load Already Saved Files' checkbox to skip data extraction with SpikeInterface and load the saved file immediately.";"";"After selecting a folder, you can start the data extraction.";"This opens a python console prompt window showing the progress of data extraction. If something should not work, activate the 'Keep Console Open' checkbox to see potential error messages (and make sure to press enter in the console after the python script finished!). If the folder should NOT contain a suitable recording, you are being informed about it here."];
            SITextWithFolder = ["Using the SpikeInterface library to extract data from a raw recording. If using SpikeInterface for the first time, you are asked for a path to the python.exe in the environment you installed SpikeInterface in. Please refer to the README how to install SpikeInterface and select the python.exe.";"";"You do not need to specify a probe design, it is automatically created based on the recording meta data. Probe design can be saved as .mat or .prb file to use in external Kilosort GUI and/or your own SpikeInterface pipeline.";"";"**NOTE**";"Please make sure that the standard folder structure created by the recording software is NOT changed and the recording folder only contains files and folder created by the recording software!";"";"After selecting a folder, it will be auto-searched for a suitable recording format. If it found one compatible with SpikeInterface, you can select it as the data extraction library to use.";"";"After SpikeInterface extracted data, it is saved in a newly created folder next to the recording folder to be loaded into NeuroMod (parent folder of recording folder/{Recording name} SpikeInterface SaveFile). You can determine the format it is saved in (for further personal use) using the 'Format to Save and Read into Matlab' dropdown menu. 'Custom format' saves a .dat file for channel data and .mat file for meta – and event data. The next time you want to load a recording you already extracted, select the 'Load Already Saved Files' checkbox to skip data extraction with SpikeInterface and load the saved file immediately.";"";"After selecting a folder, you can start the data extraction.";"This opens a python console prompt window showing the progress of data extraction. If something should not work, activate the 'Keep Console Open' checkbox to see potential error messages (and make sure to press enter in the console after the python script finished!). If the folder should NOT contain a suitable recording, you are being informed about it here."];
 
            app.AdditionalAmplificationFactorEditField_2.Enable = "on";
            app.AdditionalAmplificationFactorEditField_3.Enable = "on";
            
            if app.KeepConsoleOpen_2.Value == 1
                app.KeepConsoleOpen.Value = 0;
                app.KeepConsoleOpen.Enable = "off";
            else
                app.KeepConsoleOpen.Enable = "on";
            end

            if strcmp(app.RecordingSystemDropDown_2.Value,"NeuralEnsemble NEO Python Library")
                app.AddProbeInformationButton.Enable = "on";
                app.AddProbeInformationButton.Visible = "on";
                app.Save_Probe_Kilosort.Enable = "off";
                app.Save_Probe_Kilosort.Visible = "off";
                app.Save_Probe_SpikeInterface.Enable = "off";
                app.Save_Probe_SpikeInterface.Visible = "off";
                app.FormattoSaveProbeinLabel.Visible = "off";

                if isempty(app.ProbeInfoandPath.selectedFolder)
                    if isfield(app.ProbeInfoandPath,'Channelorder')
                        if ~isempty(app.ProbeInfoandPath.Channelorder) && sum(isnan(app.ProbeInfoandPath.Channelorder))==0
                            ProbeInfoText = ["Probe Information:";"";strcat("Nr Channel: ",num2str(app.ProbeInfoandPath.NrChannel));strcat("Channel Spacing: ",num2str(app.ProbeInfoandPath.ChannelSpacing));strcat("Nr Channel Rows: ",num2str(app.ProbeInfoandPath.NumberChannelRows));"Custom Channel Order: Yes";strcat("Offset Every Second Row: ",num2str(app.ProbeInfoandPath.OffSetRows));strcat("Nr Active Channel: ",num2str(length(app.ProbeInfoandPath.ActiveChannel)))];
                            app.TextArea.Value = [NeoTextNoFolder;"";ProbeInfoText];
                        else
                            app.TextArea.Value = NeoTextNoFolder;
                        end
    
                        app.TextArea_3.Value = "Please select a folder";
                        pause(0.2);
                        return;
                    else
                        app.TextArea.Value = NeoTextNoFolder;
                        app.TextArea_3.Value = "Please select a folder";
                        pause(0.2);
                        return;
                    end
                end

                app.FormatToSaveandReadintoMatlabDropDown.Items = {};
                app.FormatToSaveandReadintoMatlabDropDown.Items{1} = 'NEO Format to .mat Conversion';
                app.FormatToSaveandReadintoMatlabDropDown.Items{2} = 'Custom files (.dat,.mat)';
                app.FormatToSaveandReadintoMatlabDropDown.Value = app.FormatToSaveandReadintoMatlabDropDown.Items{2};

                PathToOpen = strcat(app.Mainapp.executableFolder,'\Recording Data\Raw Recordings\');
                [app.ProbeInfoandPath,ProbeInfoText,FolderContentsText] = Manage_Dataset_Module_Show_ProbeInfo_and_Path(app,app.ProbeInfoandPath,"JustChangeLibrary",PathToOpen,1,app.ProbeInfoandPath.selectedFolder,app.RecordingSystemDropDown_2.Value,app.RecordingSystemDropDown.Value,1);
                
                if strcmp(app.ProbeTrajectoryInfo,"Loaded")
                    ProbeInfoText(end+1) = "";
                    ProbeInfoText(end+2) = " Succesfully loaded Probe Trajectory.";
                end
    
                if ~isempty(ProbeInfoText)
                    app.TextArea.Value = ProbeInfoText;
                else
                    app.TextArea.Value = '';
                end
    
                if ~isempty(FolderContentsText)
                    app.TextArea_3.Value = FolderContentsText;
                else
                    app.TextArea_3.Value = '';
                end
                
                if ~isempty(app.RecordingSystemDropDown.Items)
                    app.RecordingSystemDropDown.Items{end+1} = 'NEO Format Autodetection';
                end

                app.FileTypeDropDown.Enable = "off";
                app.KeepConsoleOpen.Enable = "on";
                app.KeepConsoleOpen.Visible = 1;
                app.KeepConsoleOpen_2.Enable = "on";
                app.KeepConsoleOpen_2.Visible = 1;

                app.FileTypeDropDown.Enable = "off";
                app.FileTypeDropDown.Visible = 0;

                app.AutoDetectedRecordingSystemLabel.Text = 'Select Recording System';
                app.FileTypeLabel.Enable = "off";
                app.FileTypeLabel.Visible = 0;

            elseif strcmp(app.RecordingSystemDropDown_2.Value,"SpikeInterface Python Library")
                
                app.AddProbeInformationButton.Enable = "off";
                app.AddProbeInformationButton.Visible = "off";
                app.Save_Probe_Kilosort.Enable = "on";
                app.Save_Probe_Kilosort.Visible = "on";
                app.Save_Probe_SpikeInterface.Enable = "on";
                app.Save_Probe_SpikeInterface.Visible = "on";
                app.FormattoSaveProbeinLabel.Visible = "on";

                if isempty(app.ProbeInfoandPath.selectedFolder)
                    if isfield(app.ProbeInfoandPath,'Channelorder')
                        if ~isempty(app.ProbeInfoandPath.Channelorder) && sum(isnan(app.ProbeInfoandPath.Channelorder))==0
                            ProbeInfoText = ["Probe Information:";"";strcat("Nr Channel: ",num2str(app.ProbeInfoandPath.NrChannel));strcat("Channel Spacing: ",num2str(app.ProbeInfoandPath.ChannelSpacing));strcat("Nr Channel Rows: ",num2str(app.ProbeInfoandPath.NumberChannelRows));"Custom Channel Order: Yes";strcat("Offset Every Second Row: ",num2str(app.ProbeInfoandPath.OffSetRows));strcat("Nr Active Channel: ",num2str(length(app.ProbeInfoandPath.ActiveChannel)))];
                            app.TextArea.Value = [SITextNoFolder;"";ProbeInfoText];
                        else
                            app.TextArea.Value = SITextNoFolder;
                        end
    
                        app.TextArea_3.Value = "Please select a folder";
                        pause(0.2);
                        return;
                    else
                        app.TextArea.Value = SITextNoFolder;
                        app.TextArea_3.Value = "Please select a folder";
                        pause(0.2);
                        return;
                    end
                end

                app.FormatToSaveandReadintoMatlabDropDown.Items = {};
                app.FormatToSaveandReadintoMatlabDropDown.Items{1} = 'Custom files (.dat,.mat)';

                PathToOpen = strcat(app.Mainapp.executableFolder,'\Recording Data\Raw Recordings\');
                [app.ProbeInfoandPath,ProbeInfoText,FolderContentsText] = Manage_Dataset_Module_Show_ProbeInfo_and_Path(app,app.ProbeInfoandPath,"JustChangeLibrary",PathToOpen,1,app.ProbeInfoandPath.selectedFolder,app.RecordingSystemDropDown_2.Value,app.RecordingSystemDropDown.Value,1);
                
                if strcmp(app.ProbeTrajectoryInfo,"Loaded")
                    ProbeInfoText(end+1) = "";
                    ProbeInfoText(end+2) = " Succesfully loaded Probe Trajectory.";
                end
    
                if ~isempty(ProbeInfoText)
                    app.TextArea.Value = ProbeInfoText;
                else
                    app.TextArea.Value = '';
                end
    
                if ~isempty(FolderContentsText)
                    app.TextArea_3.Value = FolderContentsText;
                else
                    app.TextArea_3.Value = '';
                end
                
                app.FileTypeDropDown.Enable = "off";
                app.KeepConsoleOpen.Enable = "on";
                app.KeepConsoleOpen.Visible = 1;
                app.KeepConsoleOpen_2.Enable = "on";
                app.KeepConsoleOpen_2.Visible = 1;

                app.FileTypeDropDown.Enable = "off";
                app.FileTypeDropDown.Visible = 0;

                app.AutoDetectedRecordingSystemLabel.Text = 'Select Recording System';
                app.FileTypeLabel.Enable = "off";
                app.FileTypeLabel.Visible = 0;

            else
                
                app.AddProbeInformationButton.Enable = "on";
                app.AddProbeInformationButton.Visible = "on";
                app.Save_Probe_Kilosort.Enable = "off";
                app.Save_Probe_Kilosort.Visible = "off";
                app.Save_Probe_SpikeInterface.Enable = "off";
                app.Save_Probe_SpikeInterface.Visible = "off";
                app.FormattoSaveProbeinLabel.Visible = "off";

                app.FormatToSaveandReadintoMatlabDropDown.Items = {};
                
                NoFolderText = ["Raw recordings can be extracted with three different libraries - the NeuroMod internal library, the NeuralEnsemble Neo library and the SpikeInterface library, each supporting some of the same, but also different recording formats. When you select a folder, NeuroMod automatically determines the recording format, available libraries and populates the fields in this window for you to select. For more information about NEO and SpikeInterface, see the README file or select either of the libraries in the 'Data Extraction Library' dropdown and see the info appearing in this text area.";"";"NeuroMod internal format:";"";MatlabTextNoFolder];
            
                if isempty(app.ProbeInfoandPath.selectedFolder)
                    if isfield(app.ProbeInfoandPath,'Channelorder')
                        if ~isempty(app.ProbeInfoandPath.Channelorder) && sum(isnan(app.ProbeInfoandPath.Channelorder))==0
                            ProbeInfoText = ["Probe Information:";"";strcat("Nr Channel: ",num2str(app.ProbeInfoandPath.NrChannel));strcat("Channel Spacing: ",num2str(app.ProbeInfoandPath.ChannelSpacing));strcat("Nr Channel Rows: ",num2str(app.ProbeInfoandPath.NumberChannelRows));"Custom Channel Order: Yes";strcat("Offset Every Second Row: ",num2str(app.ProbeInfoandPath.OffSetRows));strcat("Nr Active Channel: ",num2str(length(app.ProbeInfoandPath.ActiveChannel)))];
                            app.TextArea.Value = [NoFolderText;"";ProbeInfoText];
                        else
                            app.TextArea.Value = NoFolderText;
                        end
    
                        app.TextArea_3.Value = "Probe Info added. Please select a folder";
                        
                    else
                        app.TextArea.Value = NoFolderText;
                        app.TextArea_3.Value = "Please select a folder and probe design";
                        
                    end

                else

                    PathToOpen = strcat(app.Mainapp.executableFolder,'\Recording Data\Raw Recordings\');
                    [app.ProbeInfoandPath,ProbeInfoText,FolderContentsText] = Manage_Dataset_Module_Show_ProbeInfo_and_Path(app,app.ProbeInfoandPath,"JustChangeLibrary",PathToOpen,1,app.ProbeInfoandPath.selectedFolder,app.RecordingSystemDropDown_2.Value,app.RecordingSystemDropDown.Value,1);
                    
                    texttoshow = ["Path or file to recording that is passed to Matlab:";"";app.ProbeInfoandPath.selectedFolder;"";MatlabTextWithFolder];

                    if isfield(app.ProbeInfoandPath,'Channelorder')
                        if ~isempty(app.ProbeInfoandPath.Channelorder) && sum(isnan(app.ProbeInfoandPath.Channelorder))==0
                            ProbeInfoText = ["Probe Information:";"";strcat("Nr Channel: ",num2str(app.ProbeInfoandPath.NrChannel));strcat("Channel Spacing: ",num2str(app.ProbeInfoandPath.ChannelSpacing));strcat("Nr Channel Rows: ",num2str(app.ProbeInfoandPath.NumberChannelRows));"Custom Channel Order: Yes";strcat("Offset Every Second Row: ",num2str(app.ProbeInfoandPath.OffSetRows));strcat("Nr Active Channel: ",num2str(length(app.ProbeInfoandPath.ActiveChannel)))];
                            app.TextArea.Value = [texttoshow;"";ProbeInfoText];
                        else
                            app.TextArea.Value = texttoshow;
                        end
    
                        app.TextArea_3.Value = "Finished Setup - data can be extracted.";
                        
                    else
                        app.TextArea.Value = texttoshow;
                        app.TextArea_3.Value = "Please select a probe design";
                        
                    end


                    if strcmp(app.ProbeTrajectoryInfo,"Loaded")
                        ProbeInfoText(end+1) = "";
                        ProbeInfoText(end+2) = " Succesfully loaded Probe Trajectory.";
                    end
        
                    if ~isempty(ProbeInfoText)
                        %app.TextArea.Value = ProbeInfoText;
                    else
                        app.TextArea.Value = '';
                    end
        
                    if ~isempty(FolderContentsText)
                        app.TextArea_3.Value = FolderContentsText;
                    else
                        app.TextArea_3.Value = '';
                    end

                end

                app.FileTypeDropDown.Enable = "on";
                app.KeepConsoleOpen.Enable = "off";
                app.KeepConsoleOpen.Visible = 0;
                app.KeepConsoleOpen_2.Enable = "off";
                app.KeepConsoleOpen_2.Visible = 0;

                app.FileTypeDropDown.Enable = "on";
                app.FileTypeDropDown.Visible = 1;
                app.RecordingSystemDropDown.Enable = "on";
                app.RecordingSystemDropDown.Visible = 1;

                app.AutoDetectedRecordingSystemLabel.Text = 'Auto Detected Recording System';
                app.AutoDetectedRecordingSystemLabel.Visible = 1;
                app.FileTypeLabel.Enable = "on";
                app.FileTypeLabel.Visible = 1;

                app.KeepConsoleOpen.Value = 0;
                app.KeepConsoleOpen_2.Value = 0;
            end

            if strcmp(app.RecordingSystemDropDown_2.Value,"NeuralEnsemble NEO Python Library")
                app.FormatToSaveandReadintoMatlabDropDown.Enable = "on";
            else
                app.FormatToSaveandReadintoMatlabDropDown.Enable = "off";
            end
            
        end

        % Value changed function: FormatToSaveandReadintoMatlabDropDown
        function FormatToSaveandReadintoMatlabDropDownValueChanged(app, event)
            value = app.FormatToSaveandReadintoMatlabDropDown.Value;
            
        end

        % Value changed function: AdditionalAmplificationFactorEditField_2
        function AdditionalAmplificationFactorEditField_2ValueChanged(app, event)
            if isempty(app.AdditionalAmplificationFactorEditField_2.Value)
                app.AdditionalAmplificationFactorEditField_2.Value = 'All';
            else

                [~,app.AdditionalAmplificationFactorEditField_2.Value] = Manage_Dataset_Check_Time_Channel_Fields([],app.AdditionalAmplificationFactorEditField_2.Value,event);
            end
        end

        % Value changed function: AdditionalAmplificationFactorEditField_3
        function AdditionalAmplificationFactorEditField_3ValueChanged(app, event)
            value = app.AdditionalAmplificationFactorEditField_3.Value;
            
            [app.AdditionalAmplificationFactorEditField_3.Value,~] = Manage_Dataset_Check_Time_Channel_Fields(app.AdditionalAmplificationFactorEditField_3.Value,[],event);
            
        end

        % Value changed function: KeepConsoleOpen
        function KeepConsoleOpenValueChanged(app, event)
           
            
        end

        % Value changed function: KeepConsoleOpen_2
        function KeepConsoleOpen_2ValueChanged(app, event)
            value = app.KeepConsoleOpen_2.Value;
            
            app.AdditionalAmplificationFactorEditField_3.Enable = "on";
            app.AdditionalAmplificationFactorEditField_2.Enable = "on";

            if value == 1 && strcmp(app.RecordingSystemDropDown_2.Value,"NeuralEnsemble NEO Python Library")
                app.AdditionalAmplificationFactorEditField_3.Enable = "off";
                app.AdditionalAmplificationFactorEditField_2.Enable = "off";

                app.AdditionalAmplificationFactorEditField_3.Value = '0,Inf';
                app.AdditionalAmplificationFactorEditField_2.Value = 'All';

                app.KeepConsoleOpen.Value = 0;
                app.KeepConsoleOpen.Enable = "off";
                
                if isfolder(app.ProbeInfoandPath.selectedFolder)
                    % Load metadata file to get time and channel to extrac
                    if strcmp(app.RecordingSystemDropDown.Value,"NEO Plexon")
                        if iscell(app.ProbeInfoandPath.selectedFolder)
                            dashindex = find(app.ProbeInfoandPath.selectedFolder{1}=='\');
                            Path = app.ProbeInfoandPath.selectedFolder{1}(1:dashindex(end)-1);
                            ModifiedSaveFile = strcat(Path," Neo SaveFile");
                        else
                            ModifiedSaveFile = strcat(app.ProbeInfoandPath.selectedFolder," Neo SaveFile");
                        end
                    else
                        dashindice = find(app.ProbeInfoandPath.selectedFolder == '\');
                        Foldername = app.ProbeInfoandPath.selectedFolder(dashindice(end)+1:end);
                        ModifiedSaveFile = strcat(app.ProbeInfoandPath.selectedFolder(1:dashindice(end)),Foldername," Neo SaveFile");
                    end
                    
                    if ~isfolder(ModifiedSaveFile)
                        warning(convertStringsToChars(strcat("NEO Output folder could not be found! Data from NEO data extraction is searched for in folder: ",ModifiedSaveFile)))
                        msgbox(convertStringsToChars(strcat("NEO Output folder could not be found! Data from NEO data extraction is searched for in folder: ",ModifiedSaveFile)))
                        Data = [];
                        SampleRate = [];
                        HeaderInfo = [];
                        RecordingType = [];
                        Time = [];
                        texttoshow = strcat("NEO Output folder could not be found! Data from NEO data extraction is searched for in folder: ",ModifiedSaveFile);
                        app.KeepConsoleOpen_2.Value = 0;
                        app.KeepConsoleOpen.Enable = "on";
                        app.KeepConsoleOpen.Visible = "on";
                        app.KeepConsoleOpen_3.Enable = "on";
                        app.KeepConsoleOpen_3.Visible = "on";
                        return;
                    end
                    
                    MetadataLocation = strcat(ModifiedSaveFile,'\NEO_Saved_MetaData.mat');
                    
                    if isfile(MetadataLocation)
                        load(MetadataLocation)
                        
                        if strcmp(individualchannel,'All')
                            app.AdditionalAmplificationFactorEditField_2.Value = 'All';
                        else
                            ChannelSelected = str2double(strsplit(individualchannel,' '));
                            ChannelSelectedField = strjoin(string(ChannelSelected),',');
                            app.AdditionalAmplificationFactorEditField_2.Value = strcat('[',ChannelSelectedField,']');
                        end
                        
                        app.AdditionalAmplificationFactorEditField_3.Value = timetoextract;
        
                        app.AdditionalAmplificationFactorEditField_2.Enable = "off";
                        app.AdditionalAmplificationFactorEditField_3.Enable = "off";
                    else
                        msgbox("MetadataLocation.mat file could not be located to automatically set Channel - and Time to extract edit fields. If you want to load an already saved .dat file, select the recording folder anyway! Folder created by NEO is automatically searched for in the same directory!");
                        app.AdditionalAmplificationFactorEditField_2.Enable = "on";
                        app.AdditionalAmplificationFactorEditField_3.Enable = "on";
                    end
                else
                    if ~isempty(app.ProbeInfoandPath.selectedFolder)
                        msgbox(strcat("Selected folder ",app.ProbeInfoandPath.selectedFolder," is not a valid path!"));
                        app.AdditionalAmplificationFactorEditField_2.Enable = "on";
                        app.AdditionalAmplificationFactorEditField_3.Enable = "on";
                    else
                        msgbox("Please select a folder first!");
                        app.AdditionalAmplificationFactorEditField_2.Enable = "on";
                        app.AdditionalAmplificationFactorEditField_3.Enable = "on";
                    end
                    app.KeepConsoleOpen_2.Value = 0;
                end
            elseif value == 1 && strcmp(app.RecordingSystemDropDown_2.Value,'SpikeInterface Python Library')
                
                app.KeepConsoleOpen_3.Value = 0;
                app.KeepConsoleOpen_3.Enable = "off";

                app.AdditionalAmplificationFactorEditField_3.Enable = "off";
                app.AdditionalAmplificationFactorEditField_2.Enable = "off";

                app.AdditionalAmplificationFactorEditField_3.Value = '0,Inf';
                app.AdditionalAmplificationFactorEditField_2.Value = 'All';

                app.KeepConsoleOpen.Value = 0;
                app.KeepConsoleOpen.Enable = "off";
                
                if isfolder(app.ProbeInfoandPath.selectedFolder)
                    % Load metadata file to get time and channel to extrac
                    dashindice = find(app.ProbeInfoandPath.selectedFolder == '\');
                    Foldername = app.ProbeInfoandPath.selectedFolder(dashindice(end)+1:end);
                    ModifiedSaveFile = strcat(app.ProbeInfoandPath.selectedFolder(1:dashindice(end)),Foldername," SpikeInterface SaveFile");
                  
                    if ~isfolder(ModifiedSaveFile)
                        warning(convertStringsToChars(strcat("SpikeInterface output folder could not be found! Data from SpikeInterface data extraction is searched for in folder: ",ModifiedSaveFile)))
                        msgbox(convertStringsToChars(strcat("SpikeInterface output folder could not be found! Data from SpikeInterface data extraction is searched for in folder: ",ModifiedSaveFile)))
                        Data = [];
                        SampleRate = [];
                        HeaderInfo = [];
                        RecordingType = [];
                        Time = [];
                        texttoshow = strcat("SpikeInterface output folder could not be found! Data from SpikeInterface data extraction is searched for in folder: ",ModifiedSaveFile);
                        app.KeepConsoleOpen_2.Value = 0;
                        app.KeepConsoleOpen.Enable = "on";
                        app.KeepConsoleOpen.Visible = "on";
                        app.KeepConsoleOpen_3.Enable = "on";
                        app.KeepConsoleOpen_3.Visible = "on";
                        return;
                    end
                    
                    MetadataLocation = strcat(ModifiedSaveFile,'\SI_Saved_MetaData.mat');
                    
                    if isfile(MetadataLocation)
                        load(MetadataLocation)
                        
                        app.AdditionalAmplificationFactorEditField_2.Value = 'All';
                        
                        if exist('TimeToExtract','var')
                            app.AdditionalAmplificationFactorEditField_3.Value = TimeToExtract;
                        else
                            app.AdditionalAmplificationFactorEditField_3.Value = '0,Inf';
                        end

                        app.AdditionalAmplificationFactorEditField_2.Enable = "off";
                        app.AdditionalAmplificationFactorEditField_3.Enable = "off";
                    else
                        msgbox("MetadataLocation.mat file could not be located to automatically set channel - and time to extract edit fields. If you want to load an already saved .dat file, select the recording folder anyway! Folder created by SpikeInterface is automatically searched for in the same directory!");
                        app.AdditionalAmplificationFactorEditField_2.Enable = "on";
                        app.AdditionalAmplificationFactorEditField_3.Enable = "on";
                    end
                else
                    if ~isempty(app.ProbeInfoandPath.selectedFolder)
                        msgbox(strcat("Selected folder ",app.ProbeInfoandPath.selectedFolder," is not a valid path!"));
                        app.AdditionalAmplificationFactorEditField_2.Enable = "on";
                        app.AdditionalAmplificationFactorEditField_3.Enable = "on";
                    else
                        msgbox("Please select a folder first!");
                        app.AdditionalAmplificationFactorEditField_2.Enable = "on";
                        app.AdditionalAmplificationFactorEditField_3.Enable = "on";
                    end
                    app.KeepConsoleOpen_2.Value = 0;
                end

            else
                if value == 1
                    app.KeepConsoleOpen.Value = 0;
                    app.KeepConsoleOpen.Enable = "off";
                else
                    app.KeepConsoleOpen.Enable = "on";
                    app.KeepConsoleOpen_3.Value = 0;
                    app.KeepConsoleOpen_3.Enable = "on";
                end
                app.AdditionalAmplificationFactorEditField_2.Enable = "on";
                app.AdditionalAmplificationFactorEditField_3.Enable = "on";
            end

            if value == 1 && strcmp(app.RecordingSystemDropDown_2.Value,"SpikeInterface Python Library")
                app.AdditionalAmplificationFactorEditField_3.Enable = "off";
                app.AdditionalAmplificationFactorEditField_2.Enable = "off";
                app.Save_Probe_Kilosort.Enable = "off";
                app.Save_Probe_SpikeInterface.Enable = "off";
                app.Save_Probe_Kilosort.Value = 0;
            elseif value == 0 && strcmp(app.RecordingSystemDropDown_2.Value,"SpikeInterface Python Library")
                app.AdditionalAmplificationFactorEditField_3.Enable = "on";
                app.AdditionalAmplificationFactorEditField_2.Enable = "on";
                app.Save_Probe_Kilosort.Enable = "on";
                app.Save_Probe_SpikeInterface.Enable = "on";
            end
        end

        % Value changed function: KeepConsoleOpen_3
        function KeepConsoleOpen_3ValueChanged(app, event)
            value = app.KeepConsoleOpen_3.Value;

            app.CorrectDCOffset = double(value);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ExtractDataWindowUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {811, 811};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {318, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ExtractDataWindowUIFigure = uifigure('Visible', 'off');
            app.ExtractDataWindowUIFigure.Name = 'Extract Data Window';
            app.ExtractDataWindowUIFigure.Icon = 'Logo.png';
            app.ExtractDataWindowUIFigure.CloseRequestFcn = createCallbackFcn(app, @ExtractDataWindowUIFigureCloseRequest, true);
            app.ExtractDataWindowUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.Menu_2 = uimenu(app.ExtractDataWindowUIFigure);
            app.TrajectoryExplorerMenu = uimenu(app.ExtractDataWindowUIFigure);
            app.TrajectoryExplorerMenu.Text = '   Trajectory Explorer   ';
            app.StartTrajectoryExplorerMenu = uimenu(app.TrajectoryExplorerMenu);
            app.StartTrajectoryExplorerMenu.MenuSelectedFcn = createCallbackFcn(app, @StartTrajectoryExplorerMenuSelected, true);
            app.StartTrajectoryExplorerMenu.Text = 'Start Trajectory Explorer (requires to download Allen mouse brain atlas)';
            app.LoadSavedTrajectoryMenu = uimenu(app.TrajectoryExplorerMenu);
            app.LoadSavedTrajectoryMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadSavedTrajectoryMenuSelected, true);
            app.LoadSavedTrajectoryMenu.Text = 'Load Saved Trajectory';
            app.GridLayout = uigridlayout(app.ExtractDataWindowUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ExtractionOptionsPanel = uipanel(app.LeftPanel);
            app.ExtractionOptionsPanel.Title = 'Extraction Options';
            app.AmplificationFactorLabel = uilabel(app.ExtractionOptionsPanel);
            app.AmplificationFactorLabel.Text = {'Additional Headstage'; 'Amplification'};
            app.AdditionalAmplificationFactorEditField = uieditfield(app.ExtractionOptionsPanel, 'text');
            app.AdditionalAmplificationFactorEditField.ValueChangedFcn = createCallbackFcn(app, @AdditionalAmplificationFactorEditFieldValueChanged, true);
            app.SelectDataFolderButton = uibutton(app.ExtractionOptionsPanel, 'push');
            app.SelectDataFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDataFolderButtonPushed, true);
            app.SelectDataFolderButton.Text = 'Select Data Folder';
            app.RecordingSystemDropDown = uidropdown(app.ExtractionOptionsPanel);
            app.RecordingSystemDropDown.Items = {};
            app.RecordingSystemDropDown.ValueChangedFcn = createCallbackFcn(app, @RecordingSystemDropDownValueChanged, true);
            app.RecordingSystemDropDown.Value = {};
            app.AutoDetectedRecordingSystemLabel = uilabel(app.ExtractionOptionsPanel);
            app.AutoDetectedRecordingSystemLabel.Text = {'Auto Detected'; 'Recording System'};
            app.FileTypeLabel = uilabel(app.ExtractionOptionsPanel);
            app.FileTypeLabel.Text = 'File Format';
            app.FileTypeDropDown = uidropdown(app.ExtractionOptionsPanel);
            app.FileTypeDropDown.Items = {};
            app.FileTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @FileTypeDropDownValueChanged, true);
            app.FileTypeDropDown.Value = {};
            app.AddProbeInformationButton = uibutton(app.ExtractionOptionsPanel, 'push');
            app.AddProbeInformationButton.ButtonPushedFcn = createCallbackFcn(app, @AddProbeInformationButtonPushed, true);
            app.AddProbeInformationButton.Text = {'Add Probe'; 'Information'};
            app.KeepConsoleOpen_2 = uicheckbox(app.ExtractionOptionsPanel);
            app.KeepConsoleOpen_2.ValueChangedFcn = createCallbackFcn(app, @KeepConsoleOpen_2ValueChanged, true);
            app.KeepConsoleOpen_2.Text = {'   Load Already '; '   Saved .dat file'};
            app.KeepConsoleOpen = uicheckbox(app.ExtractionOptionsPanel);
            app.KeepConsoleOpen.ValueChangedFcn = createCallbackFcn(app, @KeepConsoleOpenValueChanged, true);
            app.KeepConsoleOpen.Text = {'   Keep Python Console'; '   Open (Press enter in '; '   the console!)'};
            app.ExtractionOptionsLabel = uilabel(app.ExtractionOptionsPanel);
            app.ExtractionOptionsLabel.Text = 'Extraction Options';
            app.RecordingSystemDropDown_2 = uidropdown(app.ExtractionOptionsPanel);
            app.RecordingSystemDropDown_2.Items = {''};
            app.RecordingSystemDropDown_2.ValueChangedFcn = createCallbackFcn(app, @RecordingSystemDropDown_2ValueChanged, true);
            app.RecordingSystemDropDown_2.Value = '';
            app.DataExtractionLibraryLabel = uilabel(app.ExtractionOptionsPanel);
            app.DataExtractionLibraryLabel.Text = 'Data Extraction Library';
            app.FormatToSaveandReadintoMatlabDropDown = uidropdown(app.ExtractionOptionsPanel);
            app.FormatToSaveandReadintoMatlabDropDown.Items = {};
            app.FormatToSaveandReadintoMatlabDropDown.ValueChangedFcn = createCallbackFcn(app, @FormatToSaveandReadintoMatlabDropDownValueChanged, true);
            app.FormatToSaveandReadintoMatlabDropDown.Value = {};
            app.FormatToSaveandReadintoMatlabLabel = uilabel(app.ExtractionOptionsPanel);
            app.FormatToSaveandReadintoMatlabLabel.Text = 'Format To Save and Read into Matlab';
            app.AmplificationFactorLabel_2 = uilabel(app.ExtractionOptionsPanel);
            app.AmplificationFactorLabel_2.Text = {'Channel To Extract'; '(Matlab Expressions)'};
            app.AdditionalAmplificationFactorEditField_2 = uieditfield(app.ExtractionOptionsPanel, 'text');
            app.AdditionalAmplificationFactorEditField_2.ValueChangedFcn = createCallbackFcn(app, @AdditionalAmplificationFactorEditField_2ValueChanged, true);
            app.AdditionalAmplificationFactorEditField_2.Value = 'All';
            app.AmplificationFactorLabel_3 = uilabel(app.ExtractionOptionsPanel);
            app.AmplificationFactorLabel_3.Text = {'Time To Extract'; '(Start,End) in s'};
            app.AdditionalAmplificationFactorEditField_3 = uieditfield(app.ExtractionOptionsPanel, 'text');
            app.AdditionalAmplificationFactorEditField_3.ValueChangedFcn = createCallbackFcn(app, @AdditionalAmplificationFactorEditField_3ValueChanged, true);
            app.AdditionalAmplificationFactorEditField_3.Value = '0,Inf';
            app.Save_Probe_Kilosort = uicheckbox(app.ExtractionOptionsPanel);
            app.Save_Probe_Kilosort.Text = {'   Save Probe Info'; '   for Kilosort'};
            app.Save_Probe_SpikeInterface = uidropdown(app.ExtractionOptionsPanel);
            app.Save_Probe_SpikeInterface.Items = {'.mat', '.prb'};
            app.Save_Probe_SpikeInterface.Value = '.mat';
            app.FormattoSaveProbeinLabel = uilabel(app.ExtractionOptionsPanel);
            app.FormattoSaveProbeinLabel.Text = {'Format to Save'; 'Probe in'};
            app.KeepConsoleOpen_3 = uicheckbox(app.ExtractionOptionsPanel);
            app.KeepConsoleOpen_3.ValueChangedFcn = createCallbackFcn(app, @KeepConsoleOpen_3ValueChanged, true);
            app.KeepConsoleOpen_3.Text = {'   Correct DC'; '   Offset'};
            app.KeepConsoleOpen_3.Value = true;
            app.ExtractDataButton = uibutton(app.LeftPanel, 'push');
            app.ExtractDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractDataButtonPushed, true);
            app.ExtractDataButton.Text = 'Extract Data';
            app.RightPanel = uipanel(app.GridLayout);
            app.TextArea_4 = uitextarea(app.RightPanel);
            app.TextArea_4.Editable = 'off';
            app.TextArea_4.Enable = 'off';
            app.TextArea_4.Visible = 'off';
            app.TextArea = uitextarea(app.RightPanel);
            app.TextArea.Editable = 'off';
            app.InformationLabel = uilabel(app.RightPanel);
            app.InformationLabel.Text = 'Information';
            app.TextArea_3 = uitextarea(app.RightPanel);
            app.TextArea_3.Editable = 'off';
            app.FolderContentsLabel = uilabel(app.RightPanel);
            app.FolderContentsLabel.Text = 'Folder Contents';
            app.CloseButton = uibutton(app.RightPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.ExtractDataWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Extract_Data_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ExtractDataWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ExtractDataWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ExtractDataWindowUIFigure)
        end
    end
end