classdef Neo_Configuration_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NEOConfigurationWindowUIFigure  matlab.ui.Figure
        CloseButton                     matlab.ui.control.Button
        InformationTextArea             matlab.ui.control.TextArea
        InformationTextAreaLabel        matlab.ui.control.Label
        ProceedButton                   matlab.ui.control.Button
        ExtractionSettingsPanel         matlab.ui.container.Panel
        ExtractionSettingsLabel         matlab.ui.control.Label
        DataFormatDropDown              matlab.ui.control.DropDown
        DataFormatDropDownLabel         matlab.ui.control.Label
        KeepConsoleOpen_2               matlab.ui.control.CheckBox
        KeepConsoleOpen                 matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        SelectedPath = []; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, SelectedPath)
            movegui(app.NEOConfigurationWindowUIFigure, 'center');

            app.SelectedPath = SelectedPath;         
            
            texttoshow = ["When clicking proceed, an python prompt window opens showing you the progress. If you select to keep the Python console open, you have to press enter in the prompt window and maybe in the Matlab command window after it finished!";"After raw data is extracted with NEO, essential data for Neuromod is saved in the folder:";app.SelectedPath;"After the python script finishes and Matlab continuous, Neuromod automatically loads saved files in this location.";"The folder contains a .dat file with the channel data as well as a .mat file with metadata. If essential data was already saved before, enable the 'Load Already Saved .dat file' checkbox. This drastically increases speed."];

            app.InformationTextArea.Value = texttoshow;
        end

        % Button pushed function: ProceedButton
        function ProceedButtonPushed(app, event)
            uiresume(app.NEOConfigurationWindowUIFigure)
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NEOConfigurationWindowUIFigure = uifigure('Visible', 'off');
            app.NEOConfigurationWindowUIFigure.Name = 'NEO Configuration Window';
            app.NEOConfigurationWindowUIFigure.Icon = 'Logo.png';
            app.ExtractionSettingsPanel = uipanel(app.NEOConfigurationWindowUIFigure);
            app.ExtractionSettingsPanel.Title = 'Extraction Settings';
            app.KeepConsoleOpen = uicheckbox(app.ExtractionSettingsPanel);
            app.KeepConsoleOpen.Text = {'   Keep Python Console Open'; '   when Finishied (requires to '; '   press enter in the console and Matlab)'};
            app.KeepConsoleOpen_2 = uicheckbox(app.ExtractionSettingsPanel);
            app.KeepConsoleOpen_2.Text = '   Load Already Saved .dat file';
            app.DataFormatDropDownLabel = uilabel(app.ExtractionSettingsPanel);
            app.DataFormatDropDownLabel.Text = 'Data Format';
            app.DataFormatDropDown = uidropdown(app.ExtractionSettingsPanel);
            app.DataFormatDropDown.Items = {'New Open Ephys Format', 'Legacy Open Ephys Foramt'};
            app.DataFormatDropDown.Value = 'New Open Ephys Format';
            app.ExtractionSettingsLabel = uilabel(app.ExtractionSettingsPanel);
            app.ExtractionSettingsLabel.Text = 'Extraction Settings';
            app.ProceedButton = uibutton(app.NEOConfigurationWindowUIFigure, 'push');
            app.ProceedButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedButtonPushed, true);
            app.ProceedButton.Text = 'Proceed';
            app.InformationTextAreaLabel = uilabel(app.NEOConfigurationWindowUIFigure);
            app.InformationTextAreaLabel.Text = 'Information';
            app.InformationTextArea = uitextarea(app.NEOConfigurationWindowUIFigure);
            app.InformationTextArea.Value = {'When clicking proceed, an python prompt window opens showing you the progress. If you select to keep the Python console open, you have to press enter in the prompt window and maybe in the Matlab command window after it finished! '; 'After raw data is extracted with NEO, essential data for Neuromod is saved in the folder:'; ''; 'After the python script finishes and Matlab continuous, Neuromod automatically loads saved files in this location.'; ''; 'The folder contains a .dat file with the channel data as well as a .mat file with metadata. If essential data was already saved before, enable the ''Load Already Saved .dat file'' checkbox. This drastically increases speed.'; ''; ''; '   '};
            app.CloseButton = uibutton(app.NEOConfigurationWindowUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.NEOConfigurationWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Neo_Configuration_Window(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NEOConfigurationWindowUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NEOConfigurationWindowUIFigure)
        end
    end
end