classdef Probe_Layout_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ProbeLayoutWindowUIFigure      matlab.ui.Figure
        SaveProbeLayoutMenu            matlab.ui.container.Menu
        SaveProbeInfoforKilosortMenu   matlab.ui.container.Menu
        SaveProbeInfoforGUIMenu        matlab.ui.container.Menu
        SaveChannelOrderMenu           matlab.ui.container.Menu
        SaveActiveChannelMenu          matlab.ui.container.Menu
        TrajectoryExplorerMenu         matlab.ui.container.Menu
        Menu                           matlab.ui.container.Menu
        LoadSavedTrajectoryMenu        matlab.ui.container.Menu
        SetProbeGeometryLabel          matlab.ui.control.Label
        AdditionalOptionsPanel         matlab.ui.container.Panel
        ECoGArrayCheckBox              matlab.ui.control.CheckBox
        AdditionalOptionsLabel         matlab.ui.control.Label
        SwitchLeftandRightChannelNumberCheckBox  matlab.ui.control.CheckBox
        ReverseTopandBottomChannelNumberCheckBox_2  matlab.ui.control.CheckBox
        ReverseTopandBottomChannelNumberCheckBox  matlab.ui.control.CheckBox
        ShowChannelSpacingCheckBox     matlab.ui.control.CheckBox
        SetProbeInformationandContinueButton  matlab.ui.control.Button
        SetProbeGeometryPanel          matlab.ui.container.Panel
        ChannelRowsDropDown            matlab.ui.control.EditField
        ChannelSpacingumLabel_2        matlab.ui.control.Label
        CheckBox                       matlab.ui.control.CheckBox
        VerticalOffsetumEditField_2    matlab.ui.control.EditField
        VerticalOffsetBetweenChannelRowsumLabel_2  matlab.ui.control.Label
        HorizontalOffsetumEditField    matlab.ui.control.EditField
        HorizontalOffsetBetweenChannelRowsmLabel  matlab.ui.control.Label
        NrChannelEditField             matlab.ui.control.EditField
        NrChannelperRowEditFieldLabel  matlab.ui.control.Label
        VerticalOffsetumEditField      matlab.ui.control.EditField
        VerticalOffsetBetweenChannelRowsumLabel  matlab.ui.control.Label
        ChannelSpacingumEditField      matlab.ui.control.EditField
        ChannelSpacingumLabel          matlab.ui.control.Label
        CloseButton                    matlab.ui.control.Button
        InteractiveProbeViewLabel      matlab.ui.control.Label
        SetChannelInformationPanel     matlab.ui.container.Panel
        SetChannelInformationLabel     matlab.ui.control.Label
        ActiveChannelLabel_2           matlab.ui.control.Label
        ChannelOrderjustforactiveChannelLabel  matlab.ui.control.Label
        LoadChannelOrderButton         matlab.ui.control.Button
        LoadActiveChannelSelectionButton  matlab.ui.control.Button
        ActiveChannelField             matlab.ui.control.TextArea
        ChannelOrderFormat1234LeaveemptyfornocostumorderLabel_2  matlab.ui.control.Label
        ChannelOrderField              matlab.ui.control.TextArea
        ChannelOrderFormat1234LeaveemptyfornocostumorderLabel  matlab.ui.control.Label
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Mainapp = []; % Description
        ExtractRecordingWindow = [];
        FirstZoomChannel = 1;
        ProbeTrajectoryInfo = [];

        ProbeInfoandPath = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp, ExtractRecordingWindow)
            % Moce this window in the middle of thescreen
            movegui(app.ProbeLayoutWindowUIFigure, 'center');

            app.Mainapp = Mainapp;
            app.ExtractRecordingWindow = ExtractRecordingWindow;

            % Clear any previous plots and hold the plot for multiple elements
            cla(app.UIAxes);
            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";
            % Turn off the box around app.UIAxes
            box(app.UIAxes, 'off');
            app.UIAxes.FontSize = 10;
            %yTicks = get(Figure, 'YTickLabel'); % Current y-tick values
            set(app.UIAxes, 'XTickLabel',[]);

            app.SwitchLeftandRightChannelNumberCheckBox.Enable = "off";

            %% Dynamically add menu points based on savefile found in standard folder
  
            % Create parent menu
            parentMenu = uimenu(app.ProbeLayoutWindowUIFigure, 'Text', '   Load Saved Probe Information   ');
            
            % Create the secondary menu 
            filesMenu = uimenu(parentMenu, 'Text', 'Files in Standard Directory');

            % Create the second secondary menu 
            loadMenu = uimenu(parentMenu, 'Text', 'Manually Select File');

            % Extract filenames from standard folder
            DefaultFolder = strcat(app.Mainapp.executableFolder,'\Probe Layouts\Saved Probe Layouts\');

            if ~exist(DefaultFolder,"dir")
                disp(strcat("Standard Folder",DefaultFolder ," was not found. Please load probe information manually. No quick load from menu available!"));
            else
                [stringArray] = Utility_Extract_Contents_of_Folder(DefaultFolder);
                % exclude emoty and non - .mat parts
                stringArray = stringArray(stringArray ~= "");
                fileNames = stringArray(contains(stringArray,".mat"))';

                for i = 1:length(fileNames)
                    uimenu(filesMenu, 'Text', fileNames(i), 'MenuSelectedFcn', @(src,event)Menu_Extract_Data_Callback(app, fileNames(i), DefaultFolder, "ProbeLayout"));
                end
                
                uimenu(loadMenu, 'Text', 'Manually Select File', 'MenuSelectedFcn', @(src,event)Menu_Extract_Data_Load_Manually_Callback(app, "ProbeLayout"));
                
            end
            
            app = Utility_Change_Light_Dark_Mode(app,"ProbeLayout_Window");

            app.NrChannelEditField.BackgroundColor = [1,0,0];
            app.ChannelSpacingumEditField.BackgroundColor = [1,0,0];
            
            app.CheckBox.Enable = "off";
            app.VerticalOffsetumEditField_2.Enable = "off";

            app.ProbeLayoutWindowUIFigure.WindowScrollWheelFcn = @(src, event) Utility_ProbeView_Mouse_Wheel_CalBack(app,event,str2double(app.NrChannelEditField.Value));
        end

        % Button pushed function: LoadChannelOrderButton
        function LoadChannelOrderButtonPushed(app, event)
            PathToStart = strcat(app.ExtractRecordingWindow.Mainapp.executableFolder,'\Probe Layouts\Channel Orders\');
            %% Load a .mat file containing a vector with numbers indicating the true position of a channel on the probe
            % See ReadMe file in the ChannelOrder folder of the GUI 
            % stringtoshow = "Please select a .mat file containing your channel order (numbers).";
            % f = msgbox(stringtoshow);
            % Use uiget to to select a .mat file
            [file, path] = uigetfile(fullfile(PathToStart,'*.mat'), 'Select a .mat file');
            
            % Check if the user cancels the operation
            if isequal(file,0) || isequal(path,0)
                disp('Operation canceled.');
                return;
            end
            
            % Load the selected .mat file and save in the mainapp
            tempChOrder = load(fullfile(path, file));
            % Get the field names of the structure
            fieldNames = fieldnames(tempChOrder);
            fieldName = fieldNames{1};

            app.ProbeInfoandPath.Channelorder = tempChOrder.(fieldName);
            
            %% Display the channelorder in the Window 

            texttoshow = sprintf('%d, ', app.ProbeInfoandPath.Channelorder);
            % Remove the trailing comma and whitespace
            texttoshow(end-1:end) = [];

            app.ChannelOrderField.Value = texttoshow;

        end

        % Value changed function: ChannelSpacingumEditField
        function ChannelSpacingumEditFieldValueChanged(app, event)
            if ~isempty(app.ChannelSpacingumEditField.Value)
                app = Utility_Change_Light_Dark_Mode(app,"ProbeLayout_Window");
                if isempty(app.NrChannelEditField.Value)
                   app.NrChannelEditField.BackgroundColor = [1,0,0];
                end
            end
           
            if ~isempty(app.NrChannelEditField.Value) && ~isempty(app.ChannelSpacingumEditField.Value)

                if str2double(app.ChannelRowsDropDown.Value) == 1
                    if str2double(app.NrChannelEditField.Value) >=32
                        app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-31;
                    else
                        app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-(str2double(app.NrChannelEditField.Value)-1);
                    end 

                    if isempty(app.ActiveChannelField.Value{1})
                        ActiveChannel = 1:str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value);
                    else
                        ActiveChannel = str2double(strsplit(app.ActiveChannelField.Value{1},','));
                        if length(unique(ActiveChannel)) ~= length(ActiveChannel)
                            msgbox(strcat("Format error: ",num2str(length(ActiveChannel)-length(unique(ActiveChannel)))," Channel is/are present more than once. Please retry!"));
                            return;
                        end
                    end

                    if app.ReverseTopandBottomChannelNumberCheckBox.Value == 1
                        ActiveChannel = (str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value)+1)-ActiveChannel;
                    end
                    
                    if isfield(app.ProbeTrajectoryInfo,'AreaNamesLong')
                            BrainAreaInfo = app.ProbeTrajectoryInfo;
                        else
                            BrainAreaInfo = [];
                    end

                    Utility_Plot_Interactive_Probe_View(app.UIAxes,str2double(app.ChannelSpacingumEditField.Value),str2double(app.NrChannelEditField.Value),str2double(app.ChannelRowsDropDown.Value),str2double(app.HorizontalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField_2.Value),ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,ActiveChannel,app.ShowChannelSpacingCheckBox.Value,1,1,[],app.CheckBox.Value,[],app.ReverseTopandBottomChannelNumberCheckBox.Value,app.SwitchLeftandRightChannelNumberCheckBox.Value,app.ECoGArrayCheckBox.Value)

                else
                    if str2double(app.NrChannelEditField.Value)*2 >=64
                        app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-31;
                    else
                        app.FirstZoomChannel = (str2double(app.NrChannelEditField.Value)-1)-(str2double(app.NrChannelEditField.Value));
                    end 

                    if isempty(app.ActiveChannelField.Value{1})
                        ActiveChannel = 1:str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value);
                    else
                        ActiveChannel = str2double(strsplit(app.ActiveChannelField.Value{1},','));
                        if length(unique(ActiveChannel)) ~= length(ActiveChannel)
                            msgbox(strcat("Format error: ",num2str(length(ActiveChannel)-length(unique(ActiveChannel)))," Channel is/are present more than once. Please retry!"));
                            return;
                        end
                    end

                    if app.ReverseTopandBottomChannelNumberCheckBox.Value == 1
                        ActiveChannel = (str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value)+1)-ActiveChannel;
                        oddIndices = find(mod(ActiveChannel, 2) == 1);
                        evenIndices = find(mod(ActiveChannel, 2) == 0);
                        ActiveChannel(oddIndices) = ActiveChannel(oddIndices)+1;
                        ActiveChannel(evenIndices) = ActiveChannel(evenIndices)-1;
                    end

                    if isfield(app.ProbeTrajectoryInfo,'AreaNamesLong')
                        BrainAreaInfo = app.ProbeTrajectoryInfo;
                    else
                        BrainAreaInfo = [];
                    end

                    %app.FirstZoomChannel = str2double(app.NrChannelEditField.Value);
                    Utility_Plot_Interactive_Probe_View(app.UIAxes,str2double(app.ChannelSpacingumEditField.Value),str2double(app.NrChannelEditField.Value),str2double(app.ChannelRowsDropDown.Value),str2double(app.HorizontalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField_2.Value),ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,ActiveChannel,app.ShowChannelSpacingCheckBox.Value,1,1,[],app.CheckBox.Value,[],app.ReverseTopandBottomChannelNumberCheckBox.Value,app.SwitchLeftandRightChannelNumberCheckBox.Value,app.ECoGArrayCheckBox.Value)
                end


                %% Initiate Callback
                if isempty(app.UIAxes.ButtonDownFcn)
                    app.UIAxes.ButtonDownFcn = @(src1, event1) ProbeViewClickCallback(app, event1);
                end    
            
                % Add ButtonDownFcn to each line object in UIAxis
                lines = findobj(app.UIAxes, 'Type', 'line');
                
                % Add ButtonDownFcn to each line object in UIAxis
                ChannelViewRight = findobj(app.UIAxes,'Tag','ChannelViewRight');
            
                % Add ButtonDownFcn to each line object in UIAxis
                ChannelViewLeft = findobj(app.UIAxes,'Tag','ChannelViewLeft');
            
                % Add ButtonDownFcn to each line object in UIAxis
                GrayProbeFilling = findobj(app.UIAxes,'Tag','GrayProbeFilling');
                    
                %% Set the ButtonDownFcn for UIAxes to register clicks on a plotted line directly
                % lines plotted
                for i = 1:numel(lines)
                    % Call Lineclicked function if that happens
                    lines(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
            
                % Channel squares on the right
                for i = 1:numel(ChannelViewRight)
                    % Call Lineclicked function if that happens
                    ChannelViewRight(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                % Channel squares on the left
                for i = 1:numel(ChannelViewLeft)
                    % Call Lineclicked function if that happens
                    ChannelViewLeft(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                % Channel squares on the left
                for i = 1:numel(GrayProbeFilling)
                    % Call Lineclicked function if that happens
                    GrayProbeFilling(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end

                %% Create Legend
                % Create a dummy red line plot for the legend
                dummyLine = plot(app.UIAxes2, NaN, NaN, 'Color', 'red', 'LineWidth', 2);
                
                % Create a dummy yellow rectangle using a patch for the legend
                dummyRect = patch(app.UIAxes2, NaN, NaN, 'yellow', 'EdgeColor', 'none');
                
                % Create the legend and position it at the center of the plot
                legend(app.UIAxes2, [dummyLine, dummyRect], {'All Active Channel', 'Currently Active Channel'}, 'AutoUpdate', 'off');
                
                % Position the legend in the middle of the figure (over the entire figure size)
                legendPosition = [0.6, 0.9, 0.15, 0.04]; % x, y, width, height (normalized)
                set(app.UIAxes2.Legend, 'Position', legendPosition);
                
            end
        end

        % Button pushed function: SetProbeInformationandContinueButton
        function SetProbeInformationandContinueButtonPushed(app, event)
            if str2double(app.ChannelRowsDropDown.Value) >= 2
                if str2double(app.HorizontalOffsetumEditField.Value) == 0
                    msgbox("Error: Please enter a horizontal offset between channelrows bigger than 0um!");
                    app.HorizontalOffsetumEditField.BackgroundColor = 'r';
                    return;
                end
            end

            if app.CheckBox.Value == 1 && isempty(app.VerticalOffsetumEditField_2.Value) || app.CheckBox.Value == 1 && str2double(app.VerticalOffsetumEditField_2.Value) == 0
                    msgbox("Error: Please enter a 'Vertical Offset Between Every Second Row'!");
                    return;
                end
            
            ChannelOrder = str2double(strsplit(app.ChannelOrderField.Value{1},','));

            if length(unique(ChannelOrder)) ~= length(ChannelOrder)
                msgbox(strcat("Format error: ",num2str(length(ChannelOrder)-length(unique(ChannelOrder)))," Channel is/are present more than once. Please retry!"));
                return;
            end

            ActiveChannel = str2double(strsplit(app.ActiveChannelField.Value{1},','));
            if length(unique(ActiveChannel)) ~= length(ActiveChannel)
                msgbox(strcat("Format error: ",num2str(length(ActiveChannel)-length(unique(ActiveChannel)))," Channel is/are present more than once. Please retry!"));
                return;
            end

            [app.ExtractRecordingWindow.ProbeInfoandPath,ProbeInfoText,~] = Manage_Dataset_Module_Show_ProbeInfo_and_Path(app,app.ExtractRecordingWindow.ProbeInfoandPath,"ProbeInfo",[],[],[],app.ExtractRecordingWindow.RecordingSystemDropDown_2.Value,app.ExtractRecordingWindow.RecordingSystemDropDown.Value);
            
            if isempty(ProbeInfoText)
                return
            end

            if isprop(app.ExtractRecordingWindow,'AutorunManagerWindowUIFigure')
                app.ExtractRecordingWindow.TextArea_2.Value = ProbeInfoText;
            else
                if strcmp(app.ExtractRecordingWindow.ProbeTrajectoryInfo,"Loaded")
                    ProbeInfoText(end+1) = "";
                    ProbeInfoText(end+2) = " Succesfully loaded Probe Trajectory.";
                end
                app.ExtractRecordingWindow.TextArea.Value = ProbeInfoText;
            end

            delete(app);
        end

        % Callback function
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Callback function
        function SaveChannelmapasmatforKilosortButtonPushed(app, event)
            PathToStart = strcat(app.ExtractRecordingWindow.Mainapp.executableFolder,'\Probe Layouts\Kilosort Channelmaps\');

            if isempty(app.ChannelSpacingumEditField.Value)
                stringtoshow = "Error. Please first define a channel spacing on the left side of this window";
                msgbox(stringtoshow);
                return;
            end
            if isempty(app.ChannelmapNameEditField.Value)
                stringtoshow = "Error. Please first define a channel name on the right side of this window";
                msgbox(stringtoshow);
                return;
            else
                name = app.ChannelmapNameEditField.Value;
            end

            if isempty(app.NrofProbeChannelEditField.Value)
                stringtoshow = "Error. Please first define a channel number on the right side of this window";
                msgbox(stringtoshow);
                return;
            end
            
            NrChannel = str2double(app.NrofProbeChannelEditField.Value);

            xcoords = zeros(NrChannel,1);
            ycoords = 0:str2double(app.ChannelSpacingumEditField.Value):str2double(app.ChannelSpacingumEditField.Value)*NrChannel-str2double(app.ChannelSpacingumEditField.Value);

            ycoords = ycoords';
            kcoords = zeros(NrChannel,1)+1;
            connected = true(NrChannel,1);
            chanMap = 1:1:NrChannel;
            chanMap = chanMap';
            chanMap0ind = 0:1:NrChannel-1;
            chanMap0ind = chanMap0ind';

            % Prompt user for file save location and name
            [file, path] = uiputfile(fullfile(PathToSave,'*.mat'), 'Save as');
            
            if isequal(file,0) || isequal(path,0)
                disp('User canceled the operation.');
                return;
            end
    
            % Construct the full file path
            filepath = fullfile(path, file);
            % Save the variables to the .mat file
            save(filepath, 'xcoords','ycoords','kcoords','chanMap','chanMap0ind','connected');
            stringtoshow = ['Channelmap saved to: ' filepath];
            msgbox(stringtoshow);
        end

        % Value changed function: NrChannelEditField
        function NrChannelEditFieldValueChanged(app, event)
            if ~isempty(app.NrChannelEditField.Value)
                app = Utility_Change_Light_Dark_Mode(app,"ProbeLayout_Window");
                if isempty(app.ChannelSpacingumEditField.Value)
                    app.ChannelSpacingumEditField.BackgroundColor = [1,0,0];
                end
            end

            if ~isempty(app.NrChannelEditField.Value) && ~isempty(app.ChannelSpacingumEditField.Value)

                if str2double(app.ChannelRowsDropDown.Value) == 1
                    if str2double(app.NrChannelEditField.Value) >=32
                        app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-31;
                    else
                        app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-(str2double(app.NrChannelEditField.Value)-1);
                    end 

                    if isempty(app.ActiveChannelField.Value{1})
                        ActiveChannel = 1:str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value);
                    else
                        ActiveChannel = str2double(strsplit(app.ActiveChannelField.Value{1},','));
                        if length(unique(ActiveChannel)) ~= length(ActiveChannel)
                            msgbox(strcat("Format error: ",num2str(length(ActiveChannel)-length(unique(ActiveChannel)))," Channel is/are present more than once. Please retry!"));
                            return;
                        end
                    end

                    % if app.ReverseTopandBottomChannelNumberCheckBox.Value == 1
                    %     ActiveChannel = (str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value)+1)-ActiveChannel;
                    % end

                    if isfield(app.ProbeTrajectoryInfo,'AreaNamesLong')
                        BrainAreaInfo = app.ProbeTrajectoryInfo;
                    else
                        BrainAreaInfo = [];
                    end

                    Utility_Plot_Interactive_Probe_View(app.UIAxes,str2double(app.ChannelSpacingumEditField.Value),str2double(app.NrChannelEditField.Value),str2double(app.ChannelRowsDropDown.Value),str2double(app.HorizontalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField_2.Value),ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,ActiveChannel,app.ShowChannelSpacingCheckBox.Value,1,1,[],app.CheckBox.Value,[],app.ReverseTopandBottomChannelNumberCheckBox.Value,app.SwitchLeftandRightChannelNumberCheckBox.Value,app.ECoGArrayCheckBox.Value)
                else
                    if str2double(app.NrChannelEditField.Value)*2 >=64
                        app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-31;
                    else
                        app.FirstZoomChannel = (str2double(app.NrChannelEditField.Value)-1)-(str2double(app.NrChannelEditField.Value));
                    end 

                    if isempty(app.ActiveChannelField.Value{1})
                        ActiveChannel = 1:str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value);
                    else
                        ActiveChannel = str2double(strsplit(app.ActiveChannelField.Value{1},','));
                        if length(unique(ActiveChannel)) ~= length(ActiveChannel)
                            msgbox(strcat("Format error: ",num2str(length(ActiveChannel)-length(unique(ActiveChannel)))," Channel is/are present more than once. Please retry!"));
                            return;
                        end
                    end

                    if app.ReverseTopandBottomChannelNumberCheckBox.Value == 1
                        ActiveChannel = (str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value)+1)-ActiveChannel;
                        oddIndices = find(mod(ActiveChannel, 2) == 1);
                        evenIndices = find(mod(ActiveChannel, 2) == 0);
                        ActiveChannel(oddIndices) = ActiveChannel(oddIndices)+1;
                        ActiveChannel(evenIndices) = ActiveChannel(evenIndices)-1;
                    end

                    if isfield(app.ProbeTrajectoryInfo,'AreaNamesLong')
                        BrainAreaInfo = app.ProbeTrajectoryInfo;
                    else
                        BrainAreaInfo = [];
                    end

                    %app.FirstZoomChannel = str2double(app.NrChannelEditField.Value);
                    Utility_Plot_Interactive_Probe_View(app.UIAxes,str2double(app.ChannelSpacingumEditField.Value),str2double(app.NrChannelEditField.Value),str2double(app.ChannelRowsDropDown.Value),str2double(app.HorizontalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField_2.Value),ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,ActiveChannel,app.ShowChannelSpacingCheckBox.Value,1,1,[],app.CheckBox.Value,[],app.ReverseTopandBottomChannelNumberCheckBox.Value,app.SwitchLeftandRightChannelNumberCheckBox.Value,app.ECoGArrayCheckBox.Value)
                end


                %% Initiate Callback
                if isempty(app.UIAxes.ButtonDownFcn)
                    app.UIAxes.ButtonDownFcn = @(src1, event1) ProbeViewClickCallback(app, event1);
                end    
            
                % Add ButtonDownFcn to each line object in UIAxis
                lines = findobj(app.UIAxes, 'Type', 'line');
                
                % Add ButtonDownFcn to each line object in UIAxis
                ChannelViewRight = findobj(app.UIAxes,'Tag','ChannelViewRight');
            
                % Add ButtonDownFcn to each line object in UIAxis
                ChannelViewLeft = findobj(app.UIAxes,'Tag','ChannelViewLeft');
            
                % Add ButtonDownFcn to each line object in UIAxis
                GrayProbeFilling = findobj(app.UIAxes,'Tag','GrayProbeFilling');
                    
                %% Set the ButtonDownFcn for UIAxes to register clicks on a plotted line directly
                % lines plotted
                for i = 1:numel(lines)
                    % Call Lineclicked function if that happens
                    lines(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
            
                % Channel squares on the right
                for i = 1:numel(ChannelViewRight)
                    % Call Lineclicked function if that happens
                    ChannelViewRight(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                % Channel squares on the left
                for i = 1:numel(ChannelViewLeft)
                    % Call Lineclicked function if that happens
                    ChannelViewLeft(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                % Channel squares on the left
                for i = 1:numel(GrayProbeFilling)
                    % Call Lineclicked function if that happens
                    GrayProbeFilling(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end

                %% Create Legend
                % Create a dummy red line plot for the legend
                dummyLine = plot(app.UIAxes2, NaN, NaN, 'Color', 'red', 'LineWidth', 2);
                
                % Create a dummy yellow rectangle using a patch for the legend
                dummyRect = patch(app.UIAxes2, NaN, NaN, 'yellow', 'EdgeColor', 'none');
                
                % Create the legend and position it at the center of the plot
                legend(app.UIAxes2, [dummyLine, dummyRect], {'All Active Channel', 'Currently Active Channel'}, 'AutoUpdate', 'off');
                
                % Position the legend in the middle of the figure (over the entire figure size)
                legendPosition = [0.6, 0.9, 0.15, 0.04]; % x, y, width, height (normalized)
                set(app.UIAxes2.Legend, 'Position', legendPosition);
                
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed2(app, event)
            app.ExtractRecordingWindow.ProbeLayoutWindow = [];
            delete(app)
        end

        % Value changed function: HorizontalOffsetumEditField
        function HorizontalOffsetumEditFieldValueChanged(app, event)
            currentbackground = app.HorizontalOffsetumEditField.BackgroundColor ;
            %if red turn green when not 0
            if str2double(app.HorizontalOffsetumEditField.Value) > 0
                if sum(app.HorizontalOffsetumEditField.BackgroundColor == [1,0,0]) == 3
                    app.HorizontalOffsetumEditField.BackgroundColor = 'g';
                end
            else
                app.HorizontalOffsetumEditField.BackgroundColor = 'r';
            end
        end

        % Callback function
        function ChannelRowsDropDownValueChanged(app, event)
            if str2double(app.ChannelRowsDropDown.Value) >= 2
                app.SwitchLeftandRightChannelNumberCheckBox.Enable = "on";
                app.HorizontalOffsetumEditField.Enable = "on";
                app.VerticalOffsetumEditField.Enable = "on";
            else
                app.SwitchLeftandRightChannelNumberCheckBox.Enable = "off";
                app.HorizontalOffsetumEditField.Enable = "off";
                app.VerticalOffsetumEditField.Enable = "off";

                app.SwitchLeftandRightChannelNumberCheckBox.Value = 0;
                app.HorizontalOffsetumEditField.Value = "0";
                app.VerticalOffsetumEditField.Value = "0";
            end

            if str2double(app.ChannelRowsDropDown.Value) == 1
                if str2double(app.NrChannelEditField.Value) >=32
                    app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-31;
                else
                    app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-(str2double(app.NrChannelEditField.Value)-1);
                end 

                if isempty(app.ActiveChannelField.Value{1})
                    ActiveChannel = 1:str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value);
                else
                    ActiveChannel = str2double(strsplit(app.ActiveChannelField.Value{1},','));
                    if length(unique(ActiveChannel)) ~= length(ActiveChannel)
                        msgbox(strcat("Format error: ",num2str(length(ActiveChannel)-length(unique(ActiveChannel)))," Channel is/are present more than once. Please retry!"));
                        return;
                    end
                end

                if app.ReverseTopandBottomChannelNumberCheckBox.Value == 1
                    ActiveChannel = (str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value)+1)-ActiveChannel;
                end

                if isfield(app.ProbeTrajectoryInfo,'AreaNamesLong')
                        BrainAreaInfo = app.ProbeTrajectoryInfo;
                    else
                        BrainAreaInfo = [];
                end

                Utility_Plot_Interactive_Probe_View(app.UIAxes,str2double(app.ChannelSpacingumEditField.Value),str2double(app.NrChannelEditField.Value),str2double(app.ChannelRowsDropDown.Value),str2double(app.HorizontalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField_2.Value),ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,ActiveChannel,app.ShowChannelSpacingCheckBox.Value,1,1,[],app.CheckBox.Value,[],app.ReverseTopandBottomChannelNumberCheckBox.Value,app.SwitchLeftandRightChannelNumberCheckBox.Value,app.ECoGArrayCheckBox.Value)
            else
                if str2double(app.NrChannelEditField.Value)*2 >=64
                    app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-31;
                else
                    app.FirstZoomChannel = (str2double(app.NrChannelEditField.Value)-1)-(str2double(app.NrChannelEditField.Value));
                end 

                if isempty(app.ActiveChannelField.Value{1})
                    ActiveChannel = 1:str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value);
                else
                    ActiveChannel = str2double(strsplit(app.ActiveChannelField.Value{1},','));
                    if length(unique(ActiveChannel)) ~= length(ActiveChannel)
                        msgbox(strcat("Format error: ",num2str(length(ActiveChannel)-length(unique(ActiveChannel)))," Channel is/are present more than once. Please retry!"));
                        return;
                    end
                end

                if app.ReverseTopandBottomChannelNumberCheckBox.Value == 1
                    ActiveChannel = (str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value)+1)-ActiveChannel;
                    oddIndices = find(mod(ActiveChannel, 2) == 1);
                    evenIndices = find(mod(ActiveChannel, 2) == 0);
                    ActiveChannel(oddIndices) = ActiveChannel(oddIndices)+1;
                    ActiveChannel(evenIndices) = ActiveChannel(evenIndices)-1;
                end

                if isfield(app.ProbeTrajectoryInfo,'AreaNamesLong')
                    BrainAreaInfo = app.ProbeTrajectoryInfo;
                else
                    BrainAreaInfo = [];
                end

                %app.FirstZoomChannel = str2double(app.NrChannelEditField.Value);
                Utility_Plot_Interactive_Probe_View(app.UIAxes,str2double(app.ChannelSpacingumEditField.Value),str2double(app.NrChannelEditField.Value),str2double(app.ChannelRowsDropDown.Value),str2double(app.HorizontalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField_2.Value),ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,ActiveChannel,app.ShowChannelSpacingCheckBox.Value,1,1,[],app.CheckBox.Value,[],app.ReverseTopandBottomChannelNumberCheckBox.Value,app.SwitchLeftandRightChannelNumberCheckBox.Value,app.ECoGArrayCheckBox.Value)
            end

            if ~isempty(app.NrChannelEditField.Value) && ~isempty(app.ChannelSpacingumEditField.Value)
                %% Initiate Callback
                if isempty(app.UIAxes.ButtonDownFcn)
                    app.UIAxes.ButtonDownFcn = @(src1, event1) ProbeViewClickCallback(app, event1);
                end    
            
                % Add ButtonDownFcn to each line object in UIAxis
                lines = findobj(app.UIAxes, 'Type', 'line');
                
                % Add ButtonDownFcn to each line object in UIAxis
                ChannelViewRight = findobj(app.UIAxes,'Tag','ChannelViewRight');
            
                % Add ButtonDownFcn to each line object in UIAxis
                ChannelViewLeft = findobj(app.UIAxes,'Tag','ChannelViewLeft');
            
                % Add ButtonDownFcn to each line object in UIAxis
                GrayProbeFilling = findobj(app.UIAxes,'Tag','GrayProbeFilling');
                    
                %% Set the ButtonDownFcn for UIAxes to register clicks on a plotted line directly
                % lines plotted
                for i = 1:numel(lines)
                    % Call Lineclicked function if that happens
                    lines(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
            
                % Channel squares on the right
                for i = 1:numel(ChannelViewRight)
                    % Call Lineclicked function if that happens
                    ChannelViewRight(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                % Channel squares on the left
                for i = 1:numel(ChannelViewLeft)
                    % Call Lineclicked function if that happens
                    ChannelViewLeft(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                % Channel squares on the left
                for i = 1:numel(GrayProbeFilling)
                    % Call Lineclicked function if that happens
                    GrayProbeFilling(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end

                %% Add Max Value to yaxis labels so that 0 is up when yticks are flipped
            % % Get the current YLim and YTick values
            % yLimits = app.UIAxes.YLim;
            % currentYTicks = app.UIAxes.YTick;
            % currentYTickLabels = app.UIAxes.YTickLabel;
            % 
            % % Check if the maximum YLim value is already included in YTicks
            % if ~ismember(yLimits(2), currentYTicks)
            %     % If not, add the max Y value to the YTicks
            %     updatedYTicks = [currentYTicks, yLimits(2)];  % Append max Y value
            %     updatedYTickLabels = [currentYTickLabels; string(yLimits(2))];  % Append label for max Y value
            % 
            %     % Set the updated ticks and labels
            %     app.UIAxes.YTick = updatedYTicks;
            %     app.UIAxes.YTickLabel = updatedYTickLabels;
            % end
            % 
            % %% Flip yaxis
            % 
            % % Get current y-axis ticks and labels from the UIAxes object
            % currentYTicks = app.UIAxes.YTick;
            % currentYTickLabels = app.UIAxes.YTickLabel;
            % 
            % % Reverse the order of the labels
            % reversedYTickLabels = flip(currentYTickLabels);
            % 
            % % Set the new reversed labels to the y-axis
            % app.UIAxes.YTickLabel = reversedYTickLabels;

            %% Create Legend
            % Create a dummy red line plot for the legend
            dummyLine = plot(app.UIAxes2, NaN, NaN, 'Color', 'red', 'LineWidth', 2);
            
            % Create a dummy yellow rectangle using a patch for the legend
            dummyRect = patch(app.UIAxes2, NaN, NaN, 'yellow', 'EdgeColor', 'none');
            
            % Create the legend and position it at the center of the plot
            legend(app.UIAxes2, [dummyLine, dummyRect], {'All Active Channel', 'Currently Active Channel'}, 'AutoUpdate', 'off');
            
            % Position the legend in the middle of the figure (over the entire figure size)
            legendPosition = [0.6, 0.9, 0.15, 0.04]; % x, y, width, height (normalized)
            set(app.UIAxes2.Legend, 'Position', legendPosition);
                
            end
        end

        % Value changed function: VerticalOffsetumEditField
        function VerticalOffsetumEditFieldValueChanged(app, event)
            
            if ~isempty(app.VerticalOffsetumEditField.Value)
                try
                    if sum(isnan(str2double(app.VerticalOffsetumEditField.Value)))>0
                        msgbox("Wrong format. Use negative numbers like -10")
                        app.VerticalOffsetumEditField.Value = event.PreviousValue;
                    end
                    
                    if str2double(app.VerticalOffsetumEditField.Value)>0
                        app.VerticalOffsetumEditField.Value = num2str(-(str2double(app.VerticalOffsetumEditField.Value)));
                        msgbox("Only negative offsets supported so far.")
                    end
                catch
                    msgbox("Wrong format. Use negative numbers like -10")
                    app.VerticalOffsetumEditField.Value = event.PreviousValue;
                end
            end

            if ~isempty(app.NrChannelEditField.Value) && ~isempty(app.ChannelSpacingumEditField.Value)

                if str2double(app.ChannelRowsDropDown.Value) == 1
                    if str2double(app.NrChannelEditField.Value) >=32
                        app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-31;
                    else
                        app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-(str2double(app.NrChannelEditField.Value)-1);
                    end 

                    if isempty(app.ActiveChannelField.Value{1})
                        ActiveChannel = 1:str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value);
                    else
                        ActiveChannel = str2double(strsplit(app.ActiveChannelField.Value{1},','));
                        if length(unique(ActiveChannel)) ~= length(ActiveChannel)
                            msgbox(strcat("Format error: ",num2str(length(ActiveChannel)-length(unique(ActiveChannel)))," Channel is/are present more than once. Please retry!"));
                            return;
                        end
                    end

                    if app.ReverseTopandBottomChannelNumberCheckBox.Value == 1
                        ActiveChannel = (str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value)+1)-ActiveChannel;
                    end

                    if isfield(app.ProbeTrajectoryInfo,'AreaNamesLong')
                        BrainAreaInfo = app.ProbeTrajectoryInfo;
                    else
                        BrainAreaInfo = [];
                    end

                    Utility_Plot_Interactive_Probe_View(app.UIAxes,str2double(app.ChannelSpacingumEditField.Value),str2double(app.NrChannelEditField.Value),str2double(app.ChannelRowsDropDown.Value),str2double(app.HorizontalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField_2.Value),ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,ActiveChannel,app.ShowChannelSpacingCheckBox.Value,1,1,[],app.CheckBox.Value,[],app.ReverseTopandBottomChannelNumberCheckBox.Value,app.SwitchLeftandRightChannelNumberCheckBox.Value,app.ECoGArrayCheckBox.Value)
                else
                    if str2double(app.NrChannelEditField.Value)*2 >=64
                        app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-31;
                    else
                        app.FirstZoomChannel = (str2double(app.NrChannelEditField.Value)-1)-(str2double(app.NrChannelEditField.Value));
                    end 

                    if isempty(app.ActiveChannelField.Value{1})
                        ActiveChannel = 1:str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value);
                    else
                        ActiveChannel = str2double(strsplit(app.ActiveChannelField.Value{1},','));
                        if length(unique(ActiveChannel)) ~= length(ActiveChannel)
                            msgbox(strcat("Format error: ",num2str(length(ActiveChannel)-length(unique(ActiveChannel)))," Channel is/are present more than once. Please retry!"));
                            return;
                        end
                    end

                    if app.ReverseTopandBottomChannelNumberCheckBox.Value == 1
                        ActiveChannel = (str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value)+1)-ActiveChannel;
                        oddIndices = find(mod(ActiveChannel, 2) == 1);
                        evenIndices = find(mod(ActiveChannel, 2) == 0);
                        ActiveChannel(oddIndices) = ActiveChannel(oddIndices)+1;
                        ActiveChannel(evenIndices) = ActiveChannel(evenIndices)-1;
                    end

                    if isfield(app.ProbeTrajectoryInfo,'AreaNamesLong')
                        BrainAreaInfo = app.ProbeTrajectoryInfo;
                    else
                        BrainAreaInfo = [];
                    end

                    %app.FirstZoomChannel = str2double(app.NrChannelEditField.Value);
                    Utility_Plot_Interactive_Probe_View(app.UIAxes,str2double(app.ChannelSpacingumEditField.Value),str2double(app.NrChannelEditField.Value),str2double(app.ChannelRowsDropDown.Value),str2double(app.HorizontalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField_2.Value),ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,ActiveChannel,app.ShowChannelSpacingCheckBox.Value,1,1,[],app.CheckBox.Value,[],app.ReverseTopandBottomChannelNumberCheckBox.Value,app.SwitchLeftandRightChannelNumberCheckBox.Value,app.ECoGArrayCheckBox.Value)
                end


                %% Initiate Callback
                if isempty(app.UIAxes.ButtonDownFcn)
                    app.UIAxes.ButtonDownFcn = @(src1, event1) ProbeViewClickCallback(app, event1);
                end    
            
                % Add ButtonDownFcn to each line object in UIAxis
                lines = findobj(app.UIAxes, 'Type', 'line');
                
                % Add ButtonDownFcn to each line object in UIAxis
                ChannelViewRight = findobj(app.UIAxes,'Tag','ChannelViewRight');
            
                % Add ButtonDownFcn to each line object in UIAxis
                ChannelViewLeft = findobj(app.UIAxes,'Tag','ChannelViewLeft');
            
                % Add ButtonDownFcn to each line object in UIAxis
                GrayProbeFilling = findobj(app.UIAxes,'Tag','GrayProbeFilling');
                    
                %% Set the ButtonDownFcn for UIAxes to register clicks on a plotted line directly
                % lines plotted
                for i = 1:numel(lines)
                    % Call Lineclicked function if that happens
                    lines(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
            
                % Channel squares on the right
                for i = 1:numel(ChannelViewRight)
                    % Call Lineclicked function if that happens
                    ChannelViewRight(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                % Channel squares on the left
                for i = 1:numel(ChannelViewLeft)
                    % Call Lineclicked function if that happens
                    ChannelViewLeft(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                % Channel squares on the left
                for i = 1:numel(GrayProbeFilling)
                    % Call Lineclicked function if that happens
                    GrayProbeFilling(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end

                %% Add Max Value to yaxis labels so that 0 is up when yticks are flipped
            % % Get the current YLim and YTick values
            % yLimits = app.UIAxes.YLim;
            % currentYTicks = app.UIAxes.YTick;
            % currentYTickLabels = app.UIAxes.YTickLabel;
            % 
            % % Check if the maximum YLim value is already included in YTicks
            % if ~ismember(yLimits(2), currentYTicks)
            %     % If not, add the max Y value to the YTicks
            %     updatedYTicks = [currentYTicks, yLimits(2)];  % Append max Y value
            %     updatedYTickLabels = [currentYTickLabels; string(yLimits(2))];  % Append label for max Y value
            % 
            %     % Set the updated ticks and labels
            %     app.UIAxes.YTick = updatedYTicks;
            %     app.UIAxes.YTickLabel = updatedYTickLabels;
            % end
            
            % %% Flip yaxis
            % 
            % % Get current y-axis ticks and labels from the UIAxes object
            % currentYTicks = app.UIAxes.YTick;
            % currentYTickLabels = app.UIAxes.YTickLabel;
            % 
            % % Reverse the order of the labels
            % reversedYTickLabels = flip(currentYTickLabels);
            % 
            % % Set the new reversed labels to the y-axis
            % app.UIAxes.YTickLabel = reversedYTickLabels;

            %% Create Legend
            % Create a dummy red line plot for the legend
            dummyLine = plot(app.UIAxes2, NaN, NaN, 'Color', 'red', 'LineWidth', 2);
            
            % Create a dummy yellow rectangle using a patch for the legend
            dummyRect = patch(app.UIAxes2, NaN, NaN, 'yellow', 'EdgeColor', 'none');
            
            % Create the legend and position it at the center of the plot
            legend(app.UIAxes2, [dummyLine, dummyRect], {'All Active Channel', 'Currently Active Channel'}, 'AutoUpdate', 'off');
            
            % Position the legend in the middle of the figure (over the entire figure size)
            legendPosition = [0.6, 0.9, 0.15, 0.04]; % x, y, width, height (normalized)
            set(app.UIAxes2.Legend, 'Position', legendPosition);
                
            end
        end

        % Value changing function: ChannelSpacingumEditField
        function ChannelSpacingumEditFieldValueChanging(app, event)
            
        end

        % Menu selected function: SaveChannelOrderMenu
        function SaveChannelOrderMenuSelected(app, event)
            if ~isempty(app.ChannelOrderField.Value)

                PathToSave = strcat(app.ExtractRecordingWindow.Mainapp.executableFolder,'\Probe Layouts\Channel Orders\');

                % Split the string by commas
                split_array = strsplit(app.ChannelOrderField.Value{1}, ',');
                
                if length(unique(str2double(split_array))) ~= length(str2double(split_array))
                    msgbox(strcat("Format error: ",num2str(length(str2double(split_array))-length(unique(str2double(split_array))))," Channel is/are present more than once. Please retry!"));
                    return;
                end

                % Convert each element to double
                Channelorder = str2double(split_array);
                
                if isfolder(PathToSave)
                    % Prompt user for file save location and name
                    [file, path] = uiputfile(fullfile(PathToSave,'*.mat'), 'Save as');
                else
                    % Prompt user for file save location and name
                    [file, path] = uiputfile('*.mat', 'Save as');
                end
                
                if isequal(file,0) || isequal(path,0)
                    disp('User canceled the operation.');
                    return;
                end
        
                % Construct the full file path
                filepath = fullfile(path, file);
                % Save the variables to the .mat file
                save(filepath, 'Channelorder');
                stringtoshow = ['Channel Order saved to: ' filepath];
                msgbox(stringtoshow);
            else
                stringtoshow = 'No channel order specified';
                msgbox(stringtoshow);
            end
        end

        % Menu selected function: SaveProbeInfoforGUIMenu
        function SaveProbeInfoforGUIMenuSelected(app, event)
            if str2double(app.ChannelRowsDropDown.Value) >= 2
                if str2double(app.HorizontalOffsetumEditField.Value) == 0
                    msgbox("Error: Please enter a horizontal offset between channelrows bigger than 0um!");
                    app.HorizontalOffsetumEditField.BackgroundColor = 'r';
                    return;
                end
            end

            if isempty(app.ChannelSpacingumEditField.Value)
                msgbox("Please first define a channelspacing! Returning.")
                return;
            end
            if isempty(app.NrChannelEditField.Value)
                msgbox("Please first define the number of channel! Returning.")
                return;
            end

            ChannelOrder = str2double(strsplit(app.ChannelOrderField.Value{1},','));
            if length(unique(ChannelOrder)) ~= length(ChannelOrder)
                msgbox(strcat("Format error: ",num2str(length(ChannelOrder)-length(unique(ChannelOrder)))," Channel is/are present more than once. Please retry!"));
                return;
            end

            ActiveChannel = str2double(strsplit(app.ActiveChannelField.Value{1},','));
            if length(unique(ActiveChannel)) ~= length(ActiveChannel)
                msgbox(strcat("Format error: ",num2str(length(ActiveChannel)-length(unique(ActiveChannel)))," Channel is/are present more than once. Please retry!"));
                return;
            end

            PathToSave = strcat(app.ExtractRecordingWindow.Mainapp.executableFolder,'\Probe Layouts\Saved Probe Layouts\');
            
            Savefilepath = Manage_Dataset_Module_Save_Probe_For_GUI(PathToSave,app);

            %% ############################################# for this GUI window
            %% Dynamically add menu points based on savefile found in standard folder
            %% delete existing menu
            % Create parent menu
            existingParentMenu = findobj(app.ProbeLayoutWindowUIFigure, 'Text', '   Load Saved Probe Information   ');
            if ~isempty(existingParentMenu)
                delete(existingParentMenu); % Delete the existing menu if it already exists
            end
            
            %create new one
            % Create parent menu
            parentMenu = uimenu(app.ProbeLayoutWindowUIFigure, 'Text', '   Load Saved Probe Information   ');
            
            % Create the secondary menu 
            filesMenu = uimenu(parentMenu, 'Text', 'Files in Standard Directory');

            % Create the second secondary menu 
            loadMenu = uimenu(parentMenu, 'Text', 'Manually Select File');

            % Extract filenames from standard folder
            DefaultFolder = strcat(app.Mainapp.executableFolder,'\Probe Layouts\Saved Probe Layouts\');

            if ~exist(DefaultFolder,"dir")
                disp(strcat("Standard Folder",DefaultFolder ," was not found. Please load probe information manually. No quick load from menu available!"));
            else
                [stringArray] = Utility_Extract_Contents_of_Folder(DefaultFolder);
                % exclude emoty and non - .mat parts
                stringArray = stringArray(stringArray ~= "");
                fileNames = stringArray(contains(stringArray,".mat"))';

                for i = 1:length(fileNames)
                    uimenu(filesMenu, 'Text', fileNames(i), 'MenuSelectedFcn', @(src,event)Menu_Extract_Data_Callback(app, fileNames(i), DefaultFolder, "ProbeLayout"));
                end
                
                uimenu(loadMenu, 'Text', 'Manually Select File', 'MenuSelectedFcn', @(src,event)Menu_Extract_Data_Load_Manually_Callback(app, "ProbeLayout"));
                
            end

            %% ############################################# for extract data window
            %% Dynamically add menu points based on savefile found in standard folder
            %% delete existing menu
            % Create parent menu
            existingParentMenu = findobj(app.ExtractRecordingWindow.ExtractDataWindowUIFigure, 'Text', '   Load Saved Probe Information   ');
            if ~isempty(existingParentMenu)
                delete(existingParentMenu); % Delete the existing menu if it already exists
            end
            
            %create new one
            % Create parent menu
            parentMenu = uimenu(app.ExtractRecordingWindow.ExtractDataWindowUIFigure, 'Text', '   Load Saved Probe Information   ');
            
            % Create the secondary menu 
            filesMenu = uimenu(parentMenu, 'Text', 'Files in Standard Directory');

            % Create the second secondary menu 
            loadMenu = uimenu(parentMenu, 'Text', 'Manually Select File');

            % Extract filenames from standard folder
            DefaultFolder = strcat(app.Mainapp.executableFolder,'\Probe Layouts\Saved Probe Layouts\');

            if ~exist(DefaultFolder,"dir")
                disp(strcat("Standard Folder",DefaultFolder ," was not found. Please load probe information manually. No quick load from menu available!"));
            else
                [stringArray] = Utility_Extract_Contents_of_Folder(DefaultFolder);
                % exclude emoty and non - .mat parts
                stringArray = stringArray(stringArray ~= "");
                fileNames = stringArray(contains(stringArray,".mat"))';

                for i = 1:length(fileNames)
                    uimenu(filesMenu, 'Text', fileNames(i), 'MenuSelectedFcn', @(src,event)Menu_Extract_Data_Callback(app.Mainapp.ExtractDataWindow, fileNames(i), DefaultFolder, "Extract Data"));
                end
                
                uimenu(loadMenu, 'Text', 'Manually Select File', 'MenuSelectedFcn', @(src,event)Menu_Extract_Data_Load_Manually_Callback(app.Mainapp.ExtractDataWindow, "Extract Data"));
                
            end
        end

        % Menu selected function: SaveActiveChannelMenu
        function SaveActiveChannelMenuSelected(app, event)
            if ~isempty(app.ActiveChannelField.Value{1})

                PathToSave = strcat(app.ExtractRecordingWindow.Mainapp.executableFolder,'\Probe Layouts\Active Channel\');

                % Split the string by commas
                split_array = strsplit(app.ActiveChannelField.Value{1}, ',');

                if length(unique(str2double(split_array))) ~= length(str2double(split_array))
                    msgbox(strcat("Format error: ",num2str(length(str2double(split_array))-length(unique(str2double(split_array))))," Channel is/are present more than once. Please retry!"));
                    return;
                end
                
                % Convert each element to double
                ActiveChannel = str2double(split_array);
                
                if isfolder(PathToSave)
                    % Prompt user for file save location and name
                    [file, path] = uiputfile(fullfile(PathToSave,'*.mat'), 'Save as');
                else
                    % Prompt user for file save location and name
                    [file, path] = uiputfile('*.mat', 'Save as');
                end
                
                if isequal(file,0) || isequal(path,0)
                    disp('User canceled the operation.');
                    return;
                end
        
                % Construct the full file path
                filepath = fullfile(path, file);
                % Save the variables to the .mat file
                save(filepath, 'ActiveChannel');
                stringtoshow = ['Active channel map saved to: ' filepath];
                msgbox(stringtoshow);
            else
                stringtoshow = 'No active channel specified';
                msgbox(stringtoshow);
            end
        end

        % Menu selected function: SaveProbeInfoforKilosortMenu
        function SaveProbeInfoforKilosortMenuSelected(app, event)
            if ~isempty(app.NrChannelEditField.Value) && ~isempty(app.ChannelSpacingumEditField.Value) && ~isempty(app.HorizontalOffsetumEditField.Value) && ~isempty(app.VerticalOffsetumEditField.Value) && ~isempty(app.VerticalOffsetumEditField_2.Value)
                
                if str2double(app.ChannelRowsDropDown.Value) >= 2
                    if str2double(app.HorizontalOffsetumEditField.Value) == 0
                        msgbox("Error: Please enter a horizontal offset between channelrows bigger than 0um!");
                        app.HorizontalOffsetumEditField.BackgroundColor = 'r';
                        return;
                    end
                end

                if app.CheckBox.Value == 1 && isempty(app.VerticalOffsetumEditField_2.Value) || app.CheckBox.Value == 1 && str2double(app.VerticalOffsetumEditField_2.Value) == 0
                    msgbox("Error: Please enter a 'Vertical Offset Between Every Second Row'!");
                    return;
                end

                if ~isempty(app.ActiveChannelField.Value{1}) && length(strsplit(app.ActiveChannelField.Value{1},','))<=str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value)
                    NumChannel = num2str(floor((length(strsplit(app.ActiveChannelField.Value{1},',')))/str2double(app.ChannelRowsDropDown.Value)));
                else
                    NumChannel = num2str(str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value));
                end
                
                % get x and y coordinates
                if ~isempty(app.ActiveChannelField.Value{1})
                    activechannel{1} = app.ActiveChannelField.Value{1};
                else
                    activechannel{1} = strjoin(string(1:str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value)),',');
                end

                DummyStruc.NrChannel = num2str(str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value));
                
                NumChannel = num2str(str2double(app.NrChannelEditField.Value)); 
                
                [a,b] = Manage_Dataset_Save_ProbeInfo_Kilosort(DummyStruc,app.ExtractRecordingWindow.Mainapp.executableFolder,app.ChannelRowsDropDown.Value,NumChannel,app.ChannelSpacingumEditField.Value,activechannel,app.CheckBox.Value,str2double(app.VerticalOffsetumEditField_2.Value),str2double(app.VerticalOffsetumEditField.Value),str2double(app.HorizontalOffsetumEditField.Value),1);
                
            else
                stringtoshow = 'Not all necessary parameters specified!';
                msgbox(stringtoshow);
            end
        end

        % Button pushed function: LoadActiveChannelSelectionButton
        function LoadActiveChannelSelectionButtonPushed(app, event)
            PathToSave = strcat(app.ExtractRecordingWindow.Mainapp.executableFolder,'\Probe Layouts\Active Channel\');
            %% Load a .mat file containing a vector with numbers indicating the true position of a channel on the probe
            % See ReadMe file in the ChannelOrder folder of the GUI 
            % stringtoshow = "Please select a .mat file containing your channel order (numbers).";
            % f = msgbox(stringtoshow);
            % Use uiget to to select a .mat file
            
            if isfolder(PathToSave)
                [file, path] = uigetfile(fullfile(PathToSave,'*.mat'), 'Select a .mat file');
            else
                [file, path] = uigetfile('*.mat', 'Select a .mat file');
            end
            
            % Check if the user cancels the operation
            if isequal(file,0) || isequal(path,0)
                disp('Operation canceled.');
                return;
            end
            
            % Load the selected .mat file and save in the mainapp
            tempActiveChannel = load(fullfile(path, file));
            % Get the field names of the structure
            fieldNames = fieldnames(tempActiveChannel);
            fieldName = fieldNames{1};

            app.ProbeInfoandPath.ActiveChannel = tempActiveChannel.(fieldName);

            %% Display the channelorder in the Window 

            texttoshow = sprintf('%d, ', app.ProbeInfoandPath.ActiveChannel);
            % Remove the trailing comma and whitespace
            texttoshow(end-1:end) = [];

            app.ActiveChannelField.Value{1} = texttoshow;

            if str2double(app.ChannelRowsDropDown.Value) == 1
                if str2double(app.NrChannelEditField.Value) >=32
                    app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-31;
                else
                    app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-(str2double(app.NrChannelEditField.Value)-1);
                end 

                if isempty(app.ActiveChannelField.Value{1})
                    ActiveChannel = 1:str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value);
                else
                    ActiveChannel = str2double(strsplit(app.ActiveChannelField.Value{1},','));
                end

                if app.ReverseTopandBottomChannelNumberCheckBox.Value == 1
                    ActiveChannel = (str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value)+1)-ActiveChannel;
                end
                
                if isfield(app.ProbeTrajectoryInfo,'AreaNamesLong')
                    BrainAreaInfo = app.ProbeTrajectoryInfo;
                else
                    BrainAreaInfo = [];
                end

                Utility_Plot_Interactive_Probe_View(app.UIAxes,str2double(app.ChannelSpacingumEditField.Value),str2double(app.NrChannelEditField.Value),str2double(app.ChannelRowsDropDown.Value),str2double(app.HorizontalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField_2.Value),ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,ActiveChannel,app.ShowChannelSpacingCheckBox.Value,1,1,[],app.CheckBox.Value,[],app.ReverseTopandBottomChannelNumberCheckBox.Value,app.SwitchLeftandRightChannelNumberCheckBox.Value,app.ECoGArrayCheckBox.Value)
            else
                if str2double(app.NrChannelEditField.Value)*2 >=64
                    app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-31;
                else
                    app.FirstZoomChannel = (str2double(app.NrChannelEditField.Value)-1)-(str2double(app.NrChannelEditField.Value));
                end 

                if isempty(app.ActiveChannelField.Value{1})
                    ActiveChannel = 1:str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value);
                else
                    ActiveChannel = str2double(strsplit(app.ActiveChannelField.Value{1},','));
                end

                if app.ReverseTopandBottomChannelNumberCheckBox.Value == 1
                    ActiveChannel = (str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value)+1)-ActiveChannel;
                    oddIndices = find(mod(ActiveChannel, 2) == 1);
                    evenIndices = find(mod(ActiveChannel, 2) == 0);
                    ActiveChannel(oddIndices) = ActiveChannel(oddIndices)+1;
                    ActiveChannel(evenIndices) = ActiveChannel(evenIndices)-1;
                end

                if isfield(app.ProbeTrajectoryInfo,'AreaNamesLong')
                    BrainAreaInfo = app.ProbeTrajectoryInfo;
                else
                    BrainAreaInfo = [];
                end

                %app.FirstZoomChannel = str2double(app.NrChannelEditField.Value);
                Utility_Plot_Interactive_Probe_View(app.UIAxes,str2double(app.ChannelSpacingumEditField.Value),str2double(app.NrChannelEditField.Value),str2double(app.ChannelRowsDropDown.Value),str2double(app.HorizontalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField_2.Value),ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,ActiveChannel,app.ShowChannelSpacingCheckBox.Value,1,1,[],app.CheckBox.Value,[],app.ReverseTopandBottomChannelNumberCheckBox.Value,app.SwitchLeftandRightChannelNumberCheckBox.Value,app.ECoGArrayCheckBox.Value)
            end

            if ~isempty(app.NrChannelEditField.Value) && ~isempty(app.ChannelSpacingumEditField.Value)
                %% Initiate Callback
                if isempty(app.UIAxes.ButtonDownFcn)
                    app.UIAxes.ButtonDownFcn = @(src1, event1) ProbeViewClickCallback(app, event1);
                end    
            
                % Add ButtonDownFcn to each line object in UIAxis
                lines = findobj(app.UIAxes, 'Type', 'line');
                
                % Add ButtonDownFcn to each line object in UIAxis
                ChannelViewRight = findobj(app.UIAxes,'Tag','ChannelViewRight');
            
                % Add ButtonDownFcn to each line object in UIAxis
                ChannelViewLeft = findobj(app.UIAxes,'Tag','ChannelViewLeft');
            
                % Add ButtonDownFcn to each line object in UIAxis
                GrayProbeFilling = findobj(app.UIAxes,'Tag','GrayProbeFilling');
                    
                %% Set the ButtonDownFcn for UIAxes to register clicks on a plotted line directly
                % lines plotted
                for i = 1:numel(lines)
                    % Call Lineclicked function if that happens
                    lines(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
            
                % Channel squares on the right
                for i = 1:numel(ChannelViewRight)
                    % Call Lineclicked function if that happens
                    ChannelViewRight(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                % Channel squares on the left
                for i = 1:numel(ChannelViewLeft)
                    % Call Lineclicked function if that happens
                    ChannelViewLeft(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                % Channel squares on the left
                for i = 1:numel(GrayProbeFilling)
                    % Call Lineclicked function if that happens
                    GrayProbeFilling(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                
            end
        end

        % Value changed function: ActiveChannelField
        function ActiveChannelFieldValueChanged(app, event)
            if ~isempty(app.NrChannelEditField.Value) && ~isempty(app.ChannelSpacingumEditField.Value)

                TempActiveChannel{1} = [];
                if length(app.ActiveChannelField.Value)>1
                    for ncells = 1:length(app.ActiveChannelField.Value)
                        if ~isempty(app.ActiveChannelField.Value{ncells})
                            if ncells ==1
                                TempActiveChannel{1} = app.ActiveChannelField.Value{ncells};
                            else
                                TempActiveChannel{1} = [TempActiveChannel{1},',',app.ActiveChannelField.Value{ncells}];
                            end
                        end
                    end
                    app.ActiveChannelField.Value = '';
                    app.ActiveChannelField.Value{1} = TempActiveChannel{1};
                end

                if str2double(app.ChannelRowsDropDown.Value) == 1
                    if str2double(app.NrChannelEditField.Value) >=32
                        app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-31;
                    else
                        app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-(str2double(app.NrChannelEditField.Value)-1);
                    end 

                    if isempty(app.ActiveChannelField.Value{1})
                        ActiveChannel = 1:str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value);
                    else
                        ActiveChannel = str2double(strsplit(app.ActiveChannelField.Value{1},','));
                        if length(unique(ActiveChannel)) ~= length(ActiveChannel)
                            msgbox(strcat("Format error: ",num2str(length(ActiveChannel)-length(unique(ActiveChannel)))," Channel is/are present more than once. Please retry!"));
                            return;
                        end
                    end

                    if app.ReverseTopandBottomChannelNumberCheckBox.Value == 1
                        ActiveChannel = (str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value)+1)-ActiveChannel;
                    end

                    if isfield(app.ProbeTrajectoryInfo,'AreaNamesLong')
                        BrainAreaInfo = app.ProbeTrajectoryInfo;
                    else
                        BrainAreaInfo = [];
                    end

                    Utility_Plot_Interactive_Probe_View(app.UIAxes,str2double(app.ChannelSpacingumEditField.Value),str2double(app.NrChannelEditField.Value),str2double(app.ChannelRowsDropDown.Value),str2double(app.HorizontalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField_2.Value),ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,ActiveChannel,app.ShowChannelSpacingCheckBox.Value,1,1,[],app.CheckBox.Value,[],app.ReverseTopandBottomChannelNumberCheckBox.Value,app.SwitchLeftandRightChannelNumberCheckBox.Value,app.ECoGArrayCheckBox.Value)
                else
                    if str2double(app.NrChannelEditField.Value)*2 >=64
                        app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-31;
                    else
                        app.FirstZoomChannel = (str2double(app.NrChannelEditField.Value)-1)-(str2double(app.NrChannelEditField.Value));
                    end 

                    if isempty(app.ActiveChannelField.Value{1})
                        ActiveChannel = 1:str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value);
                    else
                        ActiveChannel = str2double(strsplit(app.ActiveChannelField.Value{1},','));
                        if length(unique(ActiveChannel)) ~= length(ActiveChannel)
                            msgbox(strcat("Format error: ",num2str(length(ActiveChannel)-length(unique(ActiveChannel)))," Channel is/are present more than once. Please retry!"));
                            return;
                        end
                    end

                    if app.ReverseTopandBottomChannelNumberCheckBox.Value == 1
                        ActiveChannel = (str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value)+1)-ActiveChannel;
                        oddIndices = find(mod(ActiveChannel, 2) == 1);
                        evenIndices = find(mod(ActiveChannel, 2) == 0);
                        ActiveChannel(oddIndices) = ActiveChannel(oddIndices)+1;
                        ActiveChannel(evenIndices) = ActiveChannel(evenIndices)-1;
                    end

                    if isfield(app.ProbeTrajectoryInfo,'AreaNamesLong')
                        BrainAreaInfo = app.ProbeTrajectoryInfo;
                    else
                        BrainAreaInfo = [];
                    end

                    %app.FirstZoomChannel = str2double(app.NrChannelEditField.Value);
                    Utility_Plot_Interactive_Probe_View(app.UIAxes,str2double(app.ChannelSpacingumEditField.Value),str2double(app.NrChannelEditField.Value),str2double(app.ChannelRowsDropDown.Value),str2double(app.HorizontalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField_2.Value),ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,ActiveChannel,app.ShowChannelSpacingCheckBox.Value,1,1,[],app.CheckBox.Value,[],app.ReverseTopandBottomChannelNumberCheckBox.Value,app.SwitchLeftandRightChannelNumberCheckBox.Value,app.ECoGArrayCheckBox.Value)
                end


                %% Initiate Callback
                if isempty(app.UIAxes.ButtonDownFcn)
                    app.UIAxes.ButtonDownFcn = @(src1, event1) ProbeViewClickCallback(app, event1);
                end    
            
                % Add ButtonDownFcn to each line object in UIAxis
                lines = findobj(app.UIAxes, 'Type', 'line');
                
                % Add ButtonDownFcn to each line object in UIAxis
                ChannelViewRight = findobj(app.UIAxes,'Tag','ChannelViewRight');
            
                % Add ButtonDownFcn to each line object in UIAxis
                ChannelViewLeft = findobj(app.UIAxes,'Tag','ChannelViewLeft');
            
                % Add ButtonDownFcn to each line object in UIAxis
                GrayProbeFilling = findobj(app.UIAxes,'Tag','GrayProbeFilling');
                    
                %% Set the ButtonDownFcn for UIAxes to register clicks on a plotted line directly
                % lines plotted
                for i = 1:numel(lines)
                    % Call Lineclicked function if that happens
                    lines(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
            
                % Channel squares on the right
                for i = 1:numel(ChannelViewRight)
                    % Call Lineclicked function if that happens
                    ChannelViewRight(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                % Channel squares on the left
                for i = 1:numel(ChannelViewLeft)
                    % Call Lineclicked function if that happens
                    ChannelViewLeft(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                % Channel squares on the left
                for i = 1:numel(GrayProbeFilling)
                    % Call Lineclicked function if that happens
                    GrayProbeFilling(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                
                %% Add Max Value to yaxis labels so that 0 is up when yticks are flipped
            % % Get the current YLim and YTick values
            % yLimits = app.UIAxes.YLim;
            % currentYTicks = app.UIAxes.YTick;
            % currentYTickLabels = app.UIAxes.YTickLabel;
            % 
            % % Check if the maximum YLim value is already included in YTicks
            % if ~ismember(yLimits(2), currentYTicks)
            %     % If not, add the max Y value to the YTicks
            %     updatedYTicks = [currentYTicks, yLimits(2)];  % Append max Y value
            %     updatedYTickLabels = [currentYTickLabels; string(yLimits(2))];  % Append label for max Y value
            % 
            %     % Set the updated ticks and labels
            %     app.UIAxes.YTick = updatedYTicks;
            %     app.UIAxes.YTickLabel = updatedYTickLabels;
            % end
            % 
            % %% Flip yaxis
            % 
            % % Get current y-axis ticks and labels from the UIAxes object
            % currentYTicks = app.UIAxes.YTick;
            % currentYTickLabels = app.UIAxes.YTickLabel;
            % 
            % % Reverse the order of the labels
            % reversedYTickLabels = flip(currentYTickLabels);
            % 
            % % Set the new reversed labels to the y-axis
            % app.UIAxes.YTickLabel = reversedYTickLabels;

            %% Create Legend
            % Create a dummy red line plot for the legend
            dummyLine = plot(app.UIAxes2, NaN, NaN, 'Color', 'red', 'LineWidth', 2);
            
            % Create a dummy yellow rectangle using a patch for the legend
            dummyRect = patch(app.UIAxes2, NaN, NaN, 'yellow', 'EdgeColor', 'none');
            
            % Create the legend and position it at the center of the plot
            legend(app.UIAxes2, [dummyLine, dummyRect], {'All Active Channel', 'Currently Active Channel'}, 'AutoUpdate', 'off');
            
            % Position the legend in the middle of the figure (over the entire figure size)
            legendPosition = [0.6, 0.9, 0.15, 0.04]; % x, y, width, height (normalized)
            set(app.UIAxes2.Legend, 'Position', legendPosition);

            else

                TempActiveChannel{1} = [];
                if length(app.ActiveChannelField.Value)>1
                    for ncells = 1:length(app.ActiveChannelField.Value)
                        if ~isempty(app.ActiveChannelField.Value{ncells})
                            if ncells ==1
                                TempActiveChannel{1} = app.ActiveChannelField.Value{ncells};
                            else
                                TempActiveChannel{1} = [TempActiveChannel{1},',',app.ActiveChannelField.Value{ncells}];
                            end
                        end
                    end
                    app.ActiveChannelField.Value = '';
                    app.ActiveChannelField.Value{1} = TempActiveChannel{1};
                end

                if isempty(app.ActiveChannelField.Value{1})
                    %ActiveChannel = 1:str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value);
                else
                    ActiveChannel = str2double(strsplit(app.ActiveChannelField.Value{1},','));
                    if length(unique(ActiveChannel)) ~= length(ActiveChannel)
                        msgbox(strcat("Format error: ",num2str(length(ActiveChannel)-length(unique(ActiveChannel)))," Channel is/are present more than once. Please retry!"));
                        return;
                    end
                end
            end
        end

        % Value changed function: ChannelOrderField
        function ChannelOrderFieldValueChanged(app, event)
          TempChannelOrder{1} = [];
            if length(app.ChannelOrderField.Value)>1
                for ncells = 1:length(app.ChannelOrderField.Value)
                    if ~isempty(app.ChannelOrderField.Value{ncells})
                        if ncells ==1
                            TempChannelOrder{1} = app.ChannelOrderField.Value{ncells};
                        else
                            TempChannelOrder{1} = [TempChannelOrder{1},',',app.ChannelOrderField.Value{ncells}];
                        end
                    end
                end
                app.ChannelOrderField.Value = '';
                app.ChannelOrderField.Value{1} = TempChannelOrder{1};
            end

            ChannelOrder = str2double(strsplit(app.ChannelOrderField.Value{1},','));
            if length(unique(ChannelOrder)) ~= length(ChannelOrder)
                msgbox(strcat("Format error: ",num2str(length(ChannelOrder)-length(unique(ChannelOrder)))," Channel is/are present more than once. Please retry!"));
                return;
            end
            
        end

        % Menu selected function: Menu
        function MenuSelected(app, event)
            neuropixels_trajectory_explorer;
        end

        % Menu selected function: LoadSavedTrajectoryMenu
        function LoadSavedTrajectoryMenuSelected(app, event)
            PathToSave = strcat(app.Mainapp.executableFolder,'\Probe Layouts\Trajectories\');

            if isfolder(PathToSave)
                [fileName, filePath] = uigetfile(fullfile(PathToSave,'*.mat'), 'Select a File to Load');
            else
                [fileName, filePath] = uigetfile('*.mat', 'Select a File to Load');
            end
            
            % Check if the user selected a file or canceled the dialog
            if isequal(fileName, 0)
                disp('File selection canceled.');
            else
                fullFileName = fullfile(filePath, fileName);

                % Load the file (assuming it's a .mat file)
                LoadedProbeTrajectoryInfo = load(fullFileName);
                Areas = LoadedProbeTrajectoryInfo.probe_areas;

                app.ProbeTrajectoryInfo.AreaNamesLong = Areas{1}.name;
                app.ProbeTrajectoryInfo.AreaNamesShort = Areas{1}.acronym;
                
                app.ProbeTrajectoryInfo.AreaTipDistance = Areas{1}.tip_distance;
                
                disp('Probe Trajectory Info successfully loaded.');
                
            end
            
            if ~isempty(app.NrChannelEditField.Value) && ~isempty(app.ChannelSpacingumEditField.Value)

                if str2double(app.ChannelRowsDropDown.Value) == 1
                    if str2double(app.NrChannelEditField.Value) >=32
                        app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-31;
                    else
                        app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-(str2double(app.NrChannelEditField.Value)-1);
                    end 

                    if isempty(app.ActiveChannelField.Value{1})
                        ActiveChannel = 1:str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value);
                    else
                        ActiveChannel = str2double(strsplit(app.ActiveChannelField.Value{1},','));
                    end

                    if isfield(app.ProbeTrajectoryInfo,'AreaNamesLong')
                        BrainAreaInfo = app.ProbeTrajectoryInfo;
                    else
                        BrainAreaInfo = [];
                    end

                    Utility_Plot_Interactive_Probe_View(app.UIAxes,str2double(app.ChannelSpacingumEditField.Value),str2double(app.NrChannelEditField.Value),str2double(app.ChannelRowsDropDown.Value),str2double(app.HorizontalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField_2.Value),ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,ActiveChannel,app.ShowChannelSpacingCheckBox.Value,1,1,[],app.CheckBox.Value,[],app.ReverseTopandBottomChannelNumberCheckBox.Value,app.SwitchLeftandRightChannelNumberCheckBox.Value,app.ECoGArrayCheckBox.Value)
                else
                    if str2double(app.NrChannelEditField.Value)*2 >=64
                        app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-31;
                    else
                        app.FirstZoomChannel = (str2double(app.NrChannelEditField.Value)-1)-(str2double(app.NrChannelEditField.Value));
                    end 

                    if isempty(app.ActiveChannelField.Value{1})
                        ActiveChannel = 1:str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value);
                    else
                        ActiveChannel = str2double(strsplit(app.ActiveChannelField.Value{1},','));
                    end

                    if isfield(app.ProbeTrajectoryInfo,'AreaNamesLong')
                        BrainAreaInfo = app.ProbeTrajectoryInfo;
                    else
                        BrainAreaInfo = [];
                    end

                    %app.FirstZoomChannel = str2double(app.NrChannelEditField.Value);
                    Utility_Plot_Interactive_Probe_View(app.UIAxes,str2double(app.ChannelSpacingumEditField.Value),str2double(app.NrChannelEditField.Value),str2double(app.ChannelRowsDropDown.Value),str2double(app.HorizontalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField_2.Value),ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,ActiveChannel,app.ShowChannelSpacingCheckBox.Value,1,1,[],app.CheckBox.Value,[],app.ReverseTopandBottomChannelNumberCheckBox.Value,app.SwitchLeftandRightChannelNumberCheckBox.Value,app.ECoGArrayCheckBox.Value)
                end


                %% Initiate Callback
                if isempty(app.UIAxes.ButtonDownFcn)
                    app.UIAxes.ButtonDownFcn = @(src1, event1) ProbeViewClickCallback(app, event1);
                end    
            
                % Add ButtonDownFcn to each line object in UIAxis
                lines = findobj(app.UIAxes, 'Type', 'line');
                
                % Add ButtonDownFcn to each line object in UIAxis
                ChannelViewRight = findobj(app.UIAxes,'Tag','ChannelViewRight');
            
                % Add ButtonDownFcn to each line object in UIAxis
                ChannelViewLeft = findobj(app.UIAxes,'Tag','ChannelViewLeft');
            
                % Add ButtonDownFcn to each line object in UIAxis
                GrayProbeFilling = findobj(app.UIAxes,'Tag','GrayProbeFilling');
                    
                %% Set the ButtonDownFcn for UIAxes to register clicks on a plotted line directly
                % lines plotted
                for i = 1:numel(lines)
                    % Call Lineclicked function if that happens
                    lines(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
            
                % Channel squares on the right
                for i = 1:numel(ChannelViewRight)
                    % Call Lineclicked function if that happens
                    ChannelViewRight(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                % Channel squares on the left
                for i = 1:numel(ChannelViewLeft)
                    % Call Lineclicked function if that happens
                    ChannelViewLeft(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                % Channel squares on the left
                for i = 1:numel(GrayProbeFilling)
                    % Call Lineclicked function if that happens
                    GrayProbeFilling(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                
            end
        end

        % Value changed function: ShowChannelSpacingCheckBox
        function ShowChannelSpacingCheckBoxValueChanged(app, event)
            if ~isempty(app.NrChannelEditField.Value) && ~isempty(app.ChannelSpacingumEditField.Value)
                if str2double(app.ChannelRowsDropDown.Value)>2
                    return;
                end

                if str2double(app.ChannelRowsDropDown.Value) == 1
                    if str2double(app.NrChannelEditField.Value) >=32
                        app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-31;
                    else
                        app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-(str2double(app.NrChannelEditField.Value)-1);
                    end 

                    if isempty(app.ActiveChannelField.Value{1})
                        ActiveChannel = 1:str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value);
                    else
                        ActiveChannel = str2double(strsplit(app.ActiveChannelField.Value{1},','));
                    end

                    if app.ReverseTopandBottomChannelNumberCheckBox.Value == 1
                        ActiveChannel = (str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value)+1)-ActiveChannel;
                    end

                    if isfield(app.ProbeTrajectoryInfo,'AreaNamesLong')
                        BrainAreaInfo = app.ProbeTrajectoryInfo;
                    else
                        BrainAreaInfo = [];
                    end

                    Utility_Plot_Interactive_Probe_View(app.UIAxes,str2double(app.ChannelSpacingumEditField.Value),str2double(app.NrChannelEditField.Value),str2double(app.ChannelRowsDropDown.Value),str2double(app.HorizontalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField_2.Value),ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,ActiveChannel,app.ShowChannelSpacingCheckBox.Value,1,1,[],app.CheckBox.Value,[],app.ReverseTopandBottomChannelNumberCheckBox.Value,app.SwitchLeftandRightChannelNumberCheckBox.Value,app.ECoGArrayCheckBox.Value)
                else
                    if str2double(app.NrChannelEditField.Value)*2 >=64
                        app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-31;
                    else
                        app.FirstZoomChannel = (str2double(app.NrChannelEditField.Value)-1)-(str2double(app.NrChannelEditField.Value));
                    end 

                    if isempty(app.ActiveChannelField.Value{1})
                        ActiveChannel = 1:str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value);
                    else
                        ActiveChannel = str2double(strsplit(app.ActiveChannelField.Value{1},','));
                    end

                    if app.ReverseTopandBottomChannelNumberCheckBox.Value == 1
                        ActiveChannel = (str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value)+1)-ActiveChannel;
                        oddIndices = find(mod(ActiveChannel, 2) == 1);
                        evenIndices = find(mod(ActiveChannel, 2) == 0);
                        ActiveChannel(oddIndices) = ActiveChannel(oddIndices)+1;
                        ActiveChannel(evenIndices) = ActiveChannel(evenIndices)-1;
                    end

                    if isfield(app.ProbeTrajectoryInfo,'AreaNamesLong')
                        BrainAreaInfo = app.ProbeTrajectoryInfo;
                    else
                        BrainAreaInfo = [];
                    end

                    Utility_Plot_Interactive_Probe_View(app.UIAxes,str2double(app.ChannelSpacingumEditField.Value),str2double(app.NrChannelEditField.Value),str2double(app.ChannelRowsDropDown.Value),str2double(app.HorizontalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField_2.Value),ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,ActiveChannel,app.ShowChannelSpacingCheckBox.Value,1,1,[],app.CheckBox.Value,[],app.ReverseTopandBottomChannelNumberCheckBox.Value,app.SwitchLeftandRightChannelNumberCheckBox.Value,app.ECoGArrayCheckBox.Value)
                end


                %% Initiate Callback
                if isempty(app.UIAxes.ButtonDownFcn)
                    app.UIAxes.ButtonDownFcn = @(src1, event1) ProbeViewClickCallback(app, event1);
                end    
            
                % Add ButtonDownFcn to each line object in UIAxis
                lines = findobj(app.UIAxes, 'Type', 'line');
                
                % Add ButtonDownFcn to each line object in UIAxis
                ChannelViewRight = findobj(app.UIAxes,'Tag','ChannelViewRight');
            
                % Add ButtonDownFcn to each line object in UIAxis
                ChannelViewLeft = findobj(app.UIAxes,'Tag','ChannelViewLeft');
            
                % Add ButtonDownFcn to each line object in UIAxis
                GrayProbeFilling = findobj(app.UIAxes,'Tag','GrayProbeFilling');
                    
                %% Set the ButtonDownFcn for UIAxes to register clicks on a plotted line directly
                % lines plotted
                for i = 1:numel(lines)
                    % Call Lineclicked function if that happens
                    lines(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
            
                % Channel squares on the right
                for i = 1:numel(ChannelViewRight)
                    % Call Lineclicked function if that happens
                    ChannelViewRight(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                % Channel squares on the left
                for i = 1:numel(ChannelViewLeft)
                    % Call Lineclicked function if that happens
                    ChannelViewLeft(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                % Channel squares on the left
                for i = 1:numel(GrayProbeFilling)
                    % Call Lineclicked function if that happens
                    GrayProbeFilling(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                
                %% Add Max Value to yaxis labels so that 0 is up when yticks are flipped
            % % Get the current YLim and YTick values
            % yLimits = app.UIAxes.YLim;
            % currentYTicks = app.UIAxes.YTick;
            % currentYTickLabels = app.UIAxes.YTickLabel;
            % 
            % % Check if the maximum YLim value is already included in YTicks
            % if ~ismember(yLimits(2), currentYTicks)
            %     % If not, add the max Y value to the YTicks
            %     updatedYTicks = [currentYTicks, yLimits(2)];  % Append max Y value
            %     updatedYTickLabels = [currentYTickLabels; string(yLimits(2))];  % Append label for max Y value
            % 
            %     % Set the updated ticks and labels
            %     app.UIAxes.YTick = updatedYTicks;
            %     app.UIAxes.YTickLabel = updatedYTickLabels;
            % end
            
            % %% Flip yaxis
            % 
            % % Get current y-axis ticks and labels from the UIAxes object
            % currentYTicks = app.UIAxes.YTick;
            % currentYTickLabels = app.UIAxes.YTickLabel;
            % 
            % % Reverse the order of the labels
            % reversedYTickLabels = flip(currentYTickLabels);
            % 
            % % Set the new reversed labels to the y-axis
            % app.UIAxes.YTickLabel = reversedYTickLabels;

            %% Create Legend
            % Create a dummy red line plot for the legend
            dummyLine = plot(app.UIAxes2, NaN, NaN, 'Color', 'red', 'LineWidth', 2);
            
            % Create a dummy yellow rectangle using a patch for the legend
            dummyRect = patch(app.UIAxes2, NaN, NaN, 'yellow', 'EdgeColor', 'none');
            
            % Create the legend and position it at the center of the plot
            legend(app.UIAxes2, [dummyLine, dummyRect], {'All Active Channel', 'Currently Active Channel'}, 'AutoUpdate', 'off');
            
            % Position the legend in the middle of the figure (over the entire figure size)
            legendPosition = [0.6, 0.9, 0.15, 0.04]; % x, y, width, height (normalized)
            set(app.UIAxes2.Legend, 'Position', legendPosition);

            end
            
        end

        % Value changing function: NrChannelEditField
        function NrChannelEditFieldValueChanging(app, event)
           
        end

        % Close request function: ProbeLayoutWindowUIFigure
        function ProbeLayoutWindowUIFigureCloseRequest(app, event)
            app.ExtractRecordingWindow.ProbeLayoutWindow = [];
            delete(app)
        end

        % Value changed function: VerticalOffsetumEditField_2
        function VerticalOffsetumEditField_2ValueChanged(app, event)
            if ~isempty(app.NrChannelEditField.Value) && ~isempty(app.ChannelSpacingumEditField.Value)

                if str2double(app.ChannelRowsDropDown.Value) == 1
                    if str2double(app.NrChannelEditField.Value) >=32
                        app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-31;
                    else
                        app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-(str2double(app.NrChannelEditField.Value)-1);
                    end 

                    if isempty(app.ActiveChannelField.Value{1})
                        ActiveChannel = 1:str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value);
                    else
                        ActiveChannel = str2double(strsplit(app.ActiveChannelField.Value{1},','));
                        if length(unique(ActiveChannel)) ~= length(ActiveChannel)
                            msgbox(strcat("Format error: ",num2str(length(ActiveChannel)-length(unique(ActiveChannel)))," Channel is/are present more than once. Please retry!"));
                            return;
                        end
                    end

                    if app.ReverseTopandBottomChannelNumberCheckBox.Value == 1
                        ActiveChannel = (str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value)+1)-ActiveChannel;
                    end
                    
                    if isfield(app.ProbeTrajectoryInfo,'AreaNamesLong')
                            BrainAreaInfo = app.ProbeTrajectoryInfo;
                        else
                            BrainAreaInfo = [];
                    end

                    Utility_Plot_Interactive_Probe_View(app.UIAxes,str2double(app.ChannelSpacingumEditField.Value),str2double(app.NrChannelEditField.Value),str2double(app.ChannelRowsDropDown.Value),str2double(app.HorizontalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField_2.Value),ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,ActiveChannel,app.ShowChannelSpacingCheckBox.Value,1,1,[],app.CheckBox.Value,[],app.ReverseTopandBottomChannelNumberCheckBox.Value,app.SwitchLeftandRightChannelNumberCheckBox.Value,app.ECoGArrayCheckBox.Value)
                else
                    if str2double(app.NrChannelEditField.Value)*2 >=64
                        app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-31;
                    else
                        app.FirstZoomChannel = (str2double(app.NrChannelEditField.Value)-1)-(str2double(app.NrChannelEditField.Value));
                    end 

                    if isempty(app.ActiveChannelField.Value{1})
                        ActiveChannel = 1:str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value);
                    else
                        ActiveChannel = str2double(strsplit(app.ActiveChannelField.Value{1},','));
                        if length(unique(ActiveChannel)) ~= length(ActiveChannel)
                            msgbox(strcat("Format error: ",num2str(length(ActiveChannel)-length(unique(ActiveChannel)))," Channel is/are present more than once. Please retry!"));
                            return;
                        end
                    end

                    if app.ReverseTopandBottomChannelNumberCheckBox.Value == 1
                        ActiveChannel = (str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value)+1)-ActiveChannel;
                        oddIndices = find(mod(ActiveChannel, 2) == 1);
                        evenIndices = find(mod(ActiveChannel, 2) == 0);
                        ActiveChannel(oddIndices) = ActiveChannel(oddIndices)+1;
                        ActiveChannel(evenIndices) = ActiveChannel(evenIndices)-1;
                    end

                    if isfield(app.ProbeTrajectoryInfo,'AreaNamesLong')
                        BrainAreaInfo = app.ProbeTrajectoryInfo;
                    else
                        BrainAreaInfo = [];
                    end

                    %app.FirstZoomChannel = str2double(app.NrChannelEditField.Value);
                    Utility_Plot_Interactive_Probe_View(app.UIAxes,str2double(app.ChannelSpacingumEditField.Value),str2double(app.NrChannelEditField.Value),str2double(app.ChannelRowsDropDown.Value),str2double(app.HorizontalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField_2.Value),ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,ActiveChannel,app.ShowChannelSpacingCheckBox.Value,1,1,[],app.CheckBox.Value,[],app.ReverseTopandBottomChannelNumberCheckBox.Value,app.SwitchLeftandRightChannelNumberCheckBox.Value,app.ECoGArrayCheckBox.Value)
                end


                %% Initiate Callback
                if isempty(app.UIAxes.ButtonDownFcn)
                    app.UIAxes.ButtonDownFcn = @(src1, event1) ProbeViewClickCallback(app, event1);
                end    
            
                % Add ButtonDownFcn to each line object in UIAxis
                lines = findobj(app.UIAxes, 'Type', 'line');
                
                % Add ButtonDownFcn to each line object in UIAxis
                ChannelViewRight = findobj(app.UIAxes,'Tag','ChannelViewRight');
            
                % Add ButtonDownFcn to each line object in UIAxis
                ChannelViewLeft = findobj(app.UIAxes,'Tag','ChannelViewLeft');
            
                % Add ButtonDownFcn to each line object in UIAxis
                GrayProbeFilling = findobj(app.UIAxes,'Tag','GrayProbeFilling');
                    
                %% Set the ButtonDownFcn for UIAxes to register clicks on a plotted line directly
                % lines plotted
                for i = 1:numel(lines)
                    % Call Lineclicked function if that happens
                    lines(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
            
                % Channel squares on the right
                for i = 1:numel(ChannelViewRight)
                    % Call Lineclicked function if that happens
                    ChannelViewRight(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                % Channel squares on the left
                for i = 1:numel(ChannelViewLeft)
                    % Call Lineclicked function if that happens
                    ChannelViewLeft(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                % Channel squares on the left
                for i = 1:numel(GrayProbeFilling)
                    % Call Lineclicked function if that happens
                    GrayProbeFilling(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end

                %% Create Legend
                % Create a dummy red line plot for the legend
                dummyLine = plot(app.UIAxes2, NaN, NaN, 'Color', 'red', 'LineWidth', 2);
                
                % Create a dummy yellow rectangle using a patch for the legend
                dummyRect = patch(app.UIAxes2, NaN, NaN, 'yellow', 'EdgeColor', 'none');
                
                % Create the legend and position it at the center of the plot
                legend(app.UIAxes2, [dummyLine, dummyRect], {'All Active Channel', 'Currently Active Channel'}, 'AutoUpdate', 'off');
                
                % Position the legend in the middle of the figure (over the entire figure size)
                legendPosition = [0.6, 0.9, 0.15, 0.04]; % x, y, width, height (normalized)
                set(app.UIAxes2.Legend, 'Position', legendPosition);
                
            end
            
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            
            if app.CheckBox.Value == 0
                app.VerticalOffsetumEditField_2.Value = '0';
            end

            if ~isempty(app.NrChannelEditField.Value) && ~isempty(app.ChannelSpacingumEditField.Value)

                if str2double(app.ChannelRowsDropDown.Value) == 1
                    if str2double(app.NrChannelEditField.Value) >=32
                        app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-31;
                    else
                        app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-(str2double(app.NrChannelEditField.Value)-1);
                    end 

                    if isempty(app.ActiveChannelField.Value{1})
                        ActiveChannel = 1:str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value);
                    else
                        ActiveChannel = str2double(strsplit(app.ActiveChannelField.Value{1},','));
                        if length(unique(ActiveChannel)) ~= length(ActiveChannel)
                            msgbox(strcat("Format error: ",num2str(length(ActiveChannel)-length(unique(ActiveChannel)))," Channel is/are present more than once. Please retry!"));
                            return;
                        end
                    end

                    if app.ReverseTopandBottomChannelNumberCheckBox.Value == 1
                        ActiveChannel = (str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value)+1)-ActiveChannel;
                    end
                    
                    if isfield(app.ProbeTrajectoryInfo,'AreaNamesLong')
                            BrainAreaInfo = app.ProbeTrajectoryInfo;
                        else
                            BrainAreaInfo = [];
                    end

                    Utility_Plot_Interactive_Probe_View(app.UIAxes,str2double(app.ChannelSpacingumEditField.Value),str2double(app.NrChannelEditField.Value),str2double(app.ChannelRowsDropDown.Value),str2double(app.HorizontalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField_2.Value),ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,ActiveChannel,app.ShowChannelSpacingCheckBox.Value,1,1,[],app.CheckBox.Value,[],app.ReverseTopandBottomChannelNumberCheckBox.Value,app.SwitchLeftandRightChannelNumberCheckBox.Value,app.ECoGArrayCheckBox.Value)
                else
                    if str2double(app.NrChannelEditField.Value)*2 >=64
                        app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-31;
                    else
                        app.FirstZoomChannel = (str2double(app.NrChannelEditField.Value)-1)-(str2double(app.NrChannelEditField.Value));
                    end 

                    if isempty(app.ActiveChannelField.Value{1})
                        ActiveChannel = 1:str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value);
                    else
                        ActiveChannel = str2double(strsplit(app.ActiveChannelField.Value{1},','));
                        if length(unique(ActiveChannel)) ~= length(ActiveChannel)
                            msgbox(strcat("Format error: ",num2str(length(ActiveChannel)-length(unique(ActiveChannel)))," Channel is/are present more than once. Please retry!"));
                            return;
                        end
                    end

                    if app.ReverseTopandBottomChannelNumberCheckBox.Value == 1
                        ActiveChannel = (str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value)+1)-ActiveChannel;
                        oddIndices = find(mod(ActiveChannel, 2) == 1);
                        evenIndices = find(mod(ActiveChannel, 2) == 0);
                        ActiveChannel(oddIndices) = ActiveChannel(oddIndices)+1;
                        ActiveChannel(evenIndices) = ActiveChannel(evenIndices)-1;
                    end

                    if isfield(app.ProbeTrajectoryInfo,'AreaNamesLong')
                        BrainAreaInfo = app.ProbeTrajectoryInfo;
                    else
                        BrainAreaInfo = [];
                    end

                    %app.FirstZoomChannel = str2double(app.NrChannelEditField.Value);
                    Utility_Plot_Interactive_Probe_View(app.UIAxes,str2double(app.ChannelSpacingumEditField.Value),str2double(app.NrChannelEditField.Value),str2double(app.ChannelRowsDropDown.Value),str2double(app.HorizontalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField_2.Value),ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,ActiveChannel,app.ShowChannelSpacingCheckBox.Value,1,1,[],app.CheckBox.Value,[],app.ReverseTopandBottomChannelNumberCheckBox.Value,app.SwitchLeftandRightChannelNumberCheckBox.Value,app.ECoGArrayCheckBox.Value)
                end


                %% Initiate Callback
                if isempty(app.UIAxes.ButtonDownFcn)
                    app.UIAxes.ButtonDownFcn = @(src1, event1) ProbeViewClickCallback(app, event1);
                end    
            
                % Add ButtonDownFcn to each line object in UIAxis
                lines = findobj(app.UIAxes, 'Type', 'line');
                
                % Add ButtonDownFcn to each line object in UIAxis
                ChannelViewRight = findobj(app.UIAxes,'Tag','ChannelViewRight');
            
                % Add ButtonDownFcn to each line object in UIAxis
                ChannelViewLeft = findobj(app.UIAxes,'Tag','ChannelViewLeft');
            
                % Add ButtonDownFcn to each line object in UIAxis
                GrayProbeFilling = findobj(app.UIAxes,'Tag','GrayProbeFilling');
                    
                %% Set the ButtonDownFcn for UIAxes to register clicks on a plotted line directly
                % lines plotted
                for i = 1:numel(lines)
                    % Call Lineclicked function if that happens
                    lines(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
            
                % Channel squares on the right
                for i = 1:numel(ChannelViewRight)
                    % Call Lineclicked function if that happens
                    ChannelViewRight(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                % Channel squares on the left
                for i = 1:numel(ChannelViewLeft)
                    % Call Lineclicked function if that happens
                    ChannelViewLeft(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                % Channel squares on the left
                for i = 1:numel(GrayProbeFilling)
                    % Call Lineclicked function if that happens
                    GrayProbeFilling(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end

                %% Create Legend
                % Create a dummy red line plot for the legend
                dummyLine = plot(app.UIAxes2, NaN, NaN, 'Color', 'red', 'LineWidth', 2);
                
                % Create a dummy yellow rectangle using a patch for the legend
                dummyRect = patch(app.UIAxes2, NaN, NaN, 'yellow', 'EdgeColor', 'none');
                
                % Create the legend and position it at the center of the plot
                legend(app.UIAxes2, [dummyLine, dummyRect], {'All Active Channel', 'Currently Active Channel'}, 'AutoUpdate', 'off');
                
                % Position the legend in the middle of the figure (over the entire figure size)
                legendPosition = [0.6, 0.9, 0.15, 0.04]; % x, y, width, height (normalized)
                set(app.UIAxes2.Legend, 'Position', legendPosition);
                
            end
            
        end

        % Value changed function: ReverseTopandBottomChannelNumberCheckBox
        function ReverseTopandBottomChannelNumberCheckBoxValueChanged(app, event)
            if ~isempty(app.NrChannelEditField.Value) && ~isempty(app.ChannelSpacingumEditField.Value)

                if str2double(app.ChannelRowsDropDown.Value) == 1
                    if str2double(app.NrChannelEditField.Value) >=32
                        app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-31;
                    else
                        app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-(str2double(app.NrChannelEditField.Value)-1);
                    end 

                    if isempty(app.ActiveChannelField.Value{1})
                        ActiveChannel = 1:str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value);
                    else
                        ActiveChannel = str2double(strsplit(app.ActiveChannelField.Value{1},','));
                        if length(unique(ActiveChannel)) ~= length(ActiveChannel)
                            msgbox(strcat("Format error: ",num2str(length(ActiveChannel)-length(unique(ActiveChannel)))," Channel is/are present more than once. Please retry!"));
                            return;
                        end
                    end

                    if app.ReverseTopandBottomChannelNumberCheckBox.Value == 1
                        ActiveChannel = (str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value)+1)-ActiveChannel;
                    end

                    if isfield(app.ProbeTrajectoryInfo,'AreaNamesLong')
                        BrainAreaInfo = app.ProbeTrajectoryInfo;
                    else
                        BrainAreaInfo = [];
                    end

                    Utility_Plot_Interactive_Probe_View(app.UIAxes,str2double(app.ChannelSpacingumEditField.Value),str2double(app.NrChannelEditField.Value),str2double(app.ChannelRowsDropDown.Value),str2double(app.HorizontalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField_2.Value),ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,ActiveChannel,app.ShowChannelSpacingCheckBox.Value,1,1,[],app.CheckBox.Value,[],app.ReverseTopandBottomChannelNumberCheckBox.Value,app.SwitchLeftandRightChannelNumberCheckBox.Value,app.ECoGArrayCheckBox.Value)
                else
                    if str2double(app.NrChannelEditField.Value)*2 >=64
                        app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-31;
                    else
                        app.FirstZoomChannel = (str2double(app.NrChannelEditField.Value)-1)-(str2double(app.NrChannelEditField.Value));
                    end 

                    if isempty(app.ActiveChannelField.Value{1})
                        ActiveChannel = 1:str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value);
                    else
                        ActiveChannel = str2double(strsplit(app.ActiveChannelField.Value{1},','));
                        if length(unique(ActiveChannel)) ~= length(ActiveChannel)
                            msgbox(strcat("Format error: ",num2str(length(ActiveChannel)-length(unique(ActiveChannel)))," Channel is/are present more than once. Please retry!"));
                            return;
                        end
                    end

                    if app.ReverseTopandBottomChannelNumberCheckBox.Value == 1
                        ActiveChannel = (str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value)+1)-ActiveChannel;
                        oddIndices = find(mod(ActiveChannel, 2) == 1);
                        evenIndices = find(mod(ActiveChannel, 2) == 0);
                        ActiveChannel(oddIndices) = ActiveChannel(oddIndices)+1;
                        ActiveChannel(evenIndices) = ActiveChannel(evenIndices)-1;

                        ActiveChannel = flip(sort(ActiveChannel));
                    end

                    if isfield(app.ProbeTrajectoryInfo,'AreaNamesLong')
                        BrainAreaInfo = app.ProbeTrajectoryInfo;
                    else
                        BrainAreaInfo = [];
                    end

                    %app.FirstZoomChannel = str2double(app.NrChannelEditField.Value);
                    Utility_Plot_Interactive_Probe_View(app.UIAxes,str2double(app.ChannelSpacingumEditField.Value),str2double(app.NrChannelEditField.Value),str2double(app.ChannelRowsDropDown.Value),str2double(app.HorizontalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField_2.Value),ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,ActiveChannel,app.ShowChannelSpacingCheckBox.Value,1,1,[],app.CheckBox.Value,[],app.ReverseTopandBottomChannelNumberCheckBox.Value,app.SwitchLeftandRightChannelNumberCheckBox.Value,app.ECoGArrayCheckBox.Value)
                end


                %% Initiate Callback
                if isempty(app.UIAxes.ButtonDownFcn)
                    app.UIAxes.ButtonDownFcn = @(src1, event1) ProbeViewClickCallback(app, event1);
                end    
            
                % Add ButtonDownFcn to each line object in UIAxis
                lines = findobj(app.UIAxes, 'Type', 'line');
                
                % Add ButtonDownFcn to each line object in UIAxis
                ChannelViewRight = findobj(app.UIAxes,'Tag','ChannelViewRight');
            
                % Add ButtonDownFcn to each line object in UIAxis
                ChannelViewLeft = findobj(app.UIAxes,'Tag','ChannelViewLeft');
            
                % Add ButtonDownFcn to each line object in UIAxis
                GrayProbeFilling = findobj(app.UIAxes,'Tag','GrayProbeFilling');
                    
                %% Set the ButtonDownFcn for UIAxes to register clicks on a plotted line directly
                % lines plotted
                for i = 1:numel(lines)
                    % Call Lineclicked function if that happens
                    lines(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
            
                % Channel squares on the right
                for i = 1:numel(ChannelViewRight)
                    % Call Lineclicked function if that happens
                    ChannelViewRight(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                % Channel squares on the left
                for i = 1:numel(ChannelViewLeft)
                    % Call Lineclicked function if that happens
                    ChannelViewLeft(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                % Channel squares on the left
                for i = 1:numel(GrayProbeFilling)
                    % Call Lineclicked function if that happens
                    GrayProbeFilling(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end

                %% Create Legend
                % Create a dummy red line plot for the legend
                dummyLine = plot(app.UIAxes2, NaN, NaN, 'Color', 'red', 'LineWidth', 2);
                
                % Create a dummy yellow rectangle using a patch for the legend
                dummyRect = patch(app.UIAxes2, NaN, NaN, 'yellow', 'EdgeColor', 'none');
                
                % Create the legend and position it at the center of the plot
                legend(app.UIAxes2, [dummyLine, dummyRect], {'All Active Channel', 'Currently Active Channel'}, 'AutoUpdate', 'off');
                
                % Position the legend in the middle of the figure (over the entire figure size)
                legendPosition = [0.6, 0.9, 0.15, 0.04]; % x, y, width, height (normalized)
                set(app.UIAxes2.Legend, 'Position', legendPosition);
                
            end
            
        end

        % Value changed function: SwitchLeftandRightChannelNumberCheckBox
        function SwitchLeftandRightChannelNumberCheckBoxValueChanged(app, event)
            if ~isempty(app.NrChannelEditField.Value) && ~isempty(app.ChannelSpacingumEditField.Value)

                if str2double(app.ChannelRowsDropDown.Value) >= 2
                    if str2double(app.NrChannelEditField.Value)*2 >=64
                        app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-31;
                    else
                        app.FirstZoomChannel = (str2double(app.NrChannelEditField.Value)-1)-(str2double(app.NrChannelEditField.Value));
                    end 

                    if isempty(app.ActiveChannelField.Value{1})
                        ActiveChannel = 1:str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value);
                    else
                        ActiveChannel = str2double(strsplit(app.ActiveChannelField.Value{1},','));
                        if length(unique(ActiveChannel)) ~= length(ActiveChannel)
                            msgbox(strcat("Format error: ",num2str(length(ActiveChannel)-length(unique(ActiveChannel)))," Channel is/are present more than once. Please retry!"));
                            return;
                        end
                    end

                    if app.ReverseTopandBottomChannelNumberCheckBox.Value == 1
                        ActiveChannel = (str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value)+1)-ActiveChannel;
                        oddIndices = find(mod(ActiveChannel, 2) == 1);
                        evenIndices = find(mod(ActiveChannel, 2) == 0);
                        ActiveChannel(oddIndices) = ActiveChannel(oddIndices)+1;
                        ActiveChannel(evenIndices) = ActiveChannel(evenIndices)-1;
                    end

                    if isfield(app.ProbeTrajectoryInfo,'AreaNamesLong')
                        BrainAreaInfo = app.ProbeTrajectoryInfo;
                    else
                        BrainAreaInfo = [];
                    end

                    %app.FirstZoomChannel = str2double(app.NrChannelEditField.Value);
                    Utility_Plot_Interactive_Probe_View(app.UIAxes,str2double(app.ChannelSpacingumEditField.Value),str2double(app.NrChannelEditField.Value),str2double(app.ChannelRowsDropDown.Value),str2double(app.HorizontalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField_2.Value),ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,ActiveChannel,app.ShowChannelSpacingCheckBox.Value,1,1,[],app.CheckBox.Value,[],app.ReverseTopandBottomChannelNumberCheckBox.Value,app.SwitchLeftandRightChannelNumberCheckBox.Value,app.ECoGArrayCheckBox.Value)
                end


                %% Initiate Callback
                if isempty(app.UIAxes.ButtonDownFcn)
                    app.UIAxes.ButtonDownFcn = @(src1, event1) ProbeViewClickCallback(app, event1);
                end    
            
                % Add ButtonDownFcn to each line object in UIAxis
                lines = findobj(app.UIAxes, 'Type', 'line');
                
                % Add ButtonDownFcn to each line object in UIAxis
                ChannelViewRight = findobj(app.UIAxes,'Tag','ChannelViewRight');
            
                % Add ButtonDownFcn to each line object in UIAxis
                ChannelViewLeft = findobj(app.UIAxes,'Tag','ChannelViewLeft');
            
                % Add ButtonDownFcn to each line object in UIAxis
                GrayProbeFilling = findobj(app.UIAxes,'Tag','GrayProbeFilling');
                    
                %% Set the ButtonDownFcn for UIAxes to register clicks on a plotted line directly
                % lines plotted
                for i = 1:numel(lines)
                    % Call Lineclicked function if that happens
                    lines(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
            
                % Channel squares on the right
                for i = 1:numel(ChannelViewRight)
                    % Call Lineclicked function if that happens
                    ChannelViewRight(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                % Channel squares on the left
                for i = 1:numel(ChannelViewLeft)
                    % Call Lineclicked function if that happens
                    ChannelViewLeft(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                % Channel squares on the left
                for i = 1:numel(GrayProbeFilling)
                    % Call Lineclicked function if that happens
                    GrayProbeFilling(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end

                %% Create Legend
                % Create a dummy red line plot for the legend
                dummyLine = plot(app.UIAxes2, NaN, NaN, 'Color', 'red', 'LineWidth', 2);
                
                % Create a dummy yellow rectangle using a patch for the legend
                dummyRect = patch(app.UIAxes2, NaN, NaN, 'yellow', 'EdgeColor', 'none');
                
                % Create the legend and position it at the center of the plot
                legend(app.UIAxes2, [dummyLine, dummyRect], {'All Active Channel', 'Currently Active Channel'}, 'AutoUpdate', 'off');
                
                % Position the legend in the middle of the figure (over the entire figure size)
                legendPosition = [0.6, 0.9, 0.15, 0.04]; % x, y, width, height (normalized)
                set(app.UIAxes2.Legend, 'Position', legendPosition);
                
            end
            
        end

        % Value changed function: 
        % ReverseTopandBottomChannelNumberCheckBox_2
        function ReverseTopandBottomChannelNumberCheckBox_2ValueChanged(app, event)
            value = app.ReverseTopandBottomChannelNumberCheckBox_2.Value;
            
        end

        % Value changed function: ChannelRowsDropDown
        function ChannelRowsDropDownValueChanged2(app, event)
            value = app.ChannelRowsDropDown.Value;
            
            cla(app.UIAxes);
            
            if str2double(app.ChannelRowsDropDown.Value) > 2
                app.ECoGArrayCheckBox.Value = 1;
                app.VerticalOffsetumEditField.Value = '0';
                
            else
                app.ECoGArrayCheckBox.Value = 0;
                app.VerticalOffsetumEditField.Enable = "on";
            end

            if str2double(app.ChannelRowsDropDown.Value) >= 2
                app.SwitchLeftandRightChannelNumberCheckBox.Enable = "on";
                app.HorizontalOffsetumEditField.Enable = "on";
                app.VerticalOffsetumEditField.Enable = "on";
                app.CheckBox.Enable = "on";
                app.VerticalOffsetumEditField_2.Enable = "on";
            else
                app.SwitchLeftandRightChannelNumberCheckBox.Enable = "off";
                app.HorizontalOffsetumEditField.Enable = "off";
                app.VerticalOffsetumEditField.Enable = "off";
                app.CheckBox.Value = 0;
                app.CheckBox.Enable = "off";
                app.VerticalOffsetumEditField_2.Enable = "off";

                app.SwitchLeftandRightChannelNumberCheckBox.Value = 0;
                app.HorizontalOffsetumEditField.Value = "0";
                app.VerticalOffsetumEditField.Value = "0";
            end
            
            if str2double(app.ChannelRowsDropDown.Value) > 2
                app.VerticalOffsetumEditField.Enable = "off";
                app.VerticalOffsetumEditField.Value = '0';
            end

            if str2double(app.ChannelRowsDropDown.Value) == 1
                if str2double(app.NrChannelEditField.Value) >=32
                    app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-31;
                else
                    app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-(str2double(app.NrChannelEditField.Value)-1);
                end 

                if isempty(app.ActiveChannelField.Value{1})
                    ActiveChannel = 1:str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value);
                else
                    ActiveChannel = str2double(strsplit(app.ActiveChannelField.Value{1},','));
                    if length(unique(ActiveChannel)) ~= length(ActiveChannel)
                        msgbox(strcat("Format error: ",num2str(length(ActiveChannel)-length(unique(ActiveChannel)))," Channel is/are present more than once. Please retry!"));
                        return;
                    end
                end

                if app.ReverseTopandBottomChannelNumberCheckBox.Value == 1
                    ActiveChannel = (str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value)+1)-ActiveChannel;
                end

                if isfield(app.ProbeTrajectoryInfo,'AreaNamesLong')
                        BrainAreaInfo = app.ProbeTrajectoryInfo;
                    else
                        BrainAreaInfo = [];
                end

                Utility_Plot_Interactive_Probe_View(app.UIAxes,str2double(app.ChannelSpacingumEditField.Value),str2double(app.NrChannelEditField.Value),str2double(app.ChannelRowsDropDown.Value),str2double(app.HorizontalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField_2.Value),ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,ActiveChannel,app.ShowChannelSpacingCheckBox.Value,1,1,[],app.CheckBox.Value,[],app.ReverseTopandBottomChannelNumberCheckBox.Value,app.SwitchLeftandRightChannelNumberCheckBox.Value,app.ECoGArrayCheckBox.Value)
            else
                if str2double(app.NrChannelEditField.Value)*2 >=64
                    app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-31;
                else
                    app.FirstZoomChannel = (str2double(app.NrChannelEditField.Value)-1)-(str2double(app.NrChannelEditField.Value));
                end 

                if isempty(app.ActiveChannelField.Value{1})
                    ActiveChannel = 1:str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value);
                else
                    ActiveChannel = str2double(strsplit(app.ActiveChannelField.Value{1},','));
                    if length(unique(ActiveChannel)) ~= length(ActiveChannel)
                        msgbox(strcat("Format error: ",num2str(length(ActiveChannel)-length(unique(ActiveChannel)))," Channel is/are present more than once. Please retry!"));
                        return;
                    end
                end

                if app.ReverseTopandBottomChannelNumberCheckBox.Value == 1
                    ActiveChannel = (str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value)+1)-ActiveChannel;
                    oddIndices = find(mod(ActiveChannel, 2) == 1);
                    evenIndices = find(mod(ActiveChannel, 2) == 0);
                    ActiveChannel(oddIndices) = ActiveChannel(oddIndices)+1;
                    ActiveChannel(evenIndices) = ActiveChannel(evenIndices)-1;
                end

                if isfield(app.ProbeTrajectoryInfo,'AreaNamesLong')
                    BrainAreaInfo = app.ProbeTrajectoryInfo;
                else
                    BrainAreaInfo = [];
                end

                %app.FirstZoomChannel = str2double(app.NrChannelEditField.Value);
                Utility_Plot_Interactive_Probe_View(app.UIAxes,str2double(app.ChannelSpacingumEditField.Value),str2double(app.NrChannelEditField.Value),str2double(app.ChannelRowsDropDown.Value),str2double(app.HorizontalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField_2.Value),ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,ActiveChannel,app.ShowChannelSpacingCheckBox.Value,1,1,[],app.CheckBox.Value,[],app.ReverseTopandBottomChannelNumberCheckBox.Value,app.SwitchLeftandRightChannelNumberCheckBox.Value,app.ECoGArrayCheckBox.Value)
            end

            if ~isempty(app.NrChannelEditField.Value) && ~isempty(app.ChannelSpacingumEditField.Value)
                %% Initiate Callback
                if isempty(app.UIAxes.ButtonDownFcn)
                    app.UIAxes.ButtonDownFcn = @(src1, event1) ProbeViewClickCallback(app, event1);
                end    
            
                % Add ButtonDownFcn to each line object in UIAxis
                lines = findobj(app.UIAxes, 'Type', 'line');
                
                % Add ButtonDownFcn to each line object in UIAxis
                ChannelViewRight = findobj(app.UIAxes,'Tag','ChannelViewRight');
            
                % Add ButtonDownFcn to each line object in UIAxis
                ChannelViewLeft = findobj(app.UIAxes,'Tag','ChannelViewLeft');
            
                % Add ButtonDownFcn to each line object in UIAxis
                GrayProbeFilling = findobj(app.UIAxes,'Tag','GrayProbeFilling');
                    
                %% Set the ButtonDownFcn for UIAxes to register clicks on a plotted line directly
                % lines plotted
                for i = 1:numel(lines)
                    % Call Lineclicked function if that happens
                    lines(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
            
                % Channel squares on the right
                for i = 1:numel(ChannelViewRight)
                    % Call Lineclicked function if that happens
                    ChannelViewRight(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                % Channel squares on the left
                for i = 1:numel(ChannelViewLeft)
                    % Call Lineclicked function if that happens
                    ChannelViewLeft(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                % Channel squares on the left
                for i = 1:numel(GrayProbeFilling)
                    % Call Lineclicked function if that happens
                    GrayProbeFilling(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end

                %% Add Max Value to yaxis labels so that 0 is up when yticks are flipped
            % % Get the current YLim and YTick values
            % yLimits = app.UIAxes.YLim;
            % currentYTicks = app.UIAxes.YTick;
            % currentYTickLabels = app.UIAxes.YTickLabel;
            % 
            % % Check if the maximum YLim value is already included in YTicks
            % if ~ismember(yLimits(2), currentYTicks)
            %     % If not, add the max Y value to the YTicks
            %     updatedYTicks = [currentYTicks, yLimits(2)];  % Append max Y value
            %     updatedYTickLabels = [currentYTickLabels; string(yLimits(2))];  % Append label for max Y value
            % 
            %     % Set the updated ticks and labels
            %     app.UIAxes.YTick = updatedYTicks;
            %     app.UIAxes.YTickLabel = updatedYTickLabels;
            % end
            % 
            % %% Flip yaxis
            % 
            % % Get current y-axis ticks and labels from the UIAxes object
            % currentYTicks = app.UIAxes.YTick;
            % currentYTickLabels = app.UIAxes.YTickLabel;
            % 
            % % Reverse the order of the labels
            % reversedYTickLabels = flip(currentYTickLabels);
            % 
            % % Set the new reversed labels to the y-axis
            % app.UIAxes.YTickLabel = reversedYTickLabels;

            %% Create Legend
            % Create a dummy red line plot for the legend
            dummyLine = plot(app.UIAxes2, NaN, NaN, 'Color', 'red', 'LineWidth', 2);
            
            % Create a dummy yellow rectangle using a patch for the legend
            dummyRect = patch(app.UIAxes2, NaN, NaN, 'yellow', 'EdgeColor', 'none');
            
            % Create the legend and position it at the center of the plot
            legend(app.UIAxes2, [dummyLine, dummyRect], {'All Active Channel', 'Currently Active Channel'}, 'AutoUpdate', 'off');
            
            % Position the legend in the middle of the figure (over the entire figure size)
            legendPosition = [0.6, 0.9, 0.15, 0.04]; % x, y, width, height (normalized)
            set(app.UIAxes2.Legend, 'Position', legendPosition);
                
            end
        end

        % Value changed function: ECoGArrayCheckBox
        function ECoGArrayCheckBoxValueChanged(app, event)
            cla(app.UIAxes);
            
            if ~isempty(app.NrChannelEditField.Value) && ~isempty(app.ChannelSpacingumEditField.Value)

                if str2double(app.ChannelRowsDropDown.Value) == 1
                    if str2double(app.NrChannelEditField.Value) >=32
                        app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-31;
                    else
                        app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-(str2double(app.NrChannelEditField.Value)-1);
                    end 

                    if isempty(app.ActiveChannelField.Value{1})
                        ActiveChannel = 1:str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value);
                    else
                        ActiveChannel = str2double(strsplit(app.ActiveChannelField.Value{1},','));
                        if length(unique(ActiveChannel)) ~= length(ActiveChannel)
                            msgbox(strcat("Format error: ",num2str(length(ActiveChannel)-length(unique(ActiveChannel)))," Channel is/are present more than once. Please retry!"));
                            return;
                        end
                    end

                    if app.ReverseTopandBottomChannelNumberCheckBox.Value == 1
                        ActiveChannel = (str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value)+1)-ActiveChannel;
                    end
                    
                    if isfield(app.ProbeTrajectoryInfo,'AreaNamesLong')
                            BrainAreaInfo = app.ProbeTrajectoryInfo;
                        else
                            BrainAreaInfo = [];
                    end

                    Utility_Plot_Interactive_Probe_View(app.UIAxes,str2double(app.ChannelSpacingumEditField.Value),str2double(app.NrChannelEditField.Value),str2double(app.ChannelRowsDropDown.Value),str2double(app.HorizontalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField_2.Value),ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,ActiveChannel,app.ShowChannelSpacingCheckBox.Value,1,1,[],app.CheckBox.Value,[],app.ReverseTopandBottomChannelNumberCheckBox.Value,app.SwitchLeftandRightChannelNumberCheckBox.Value,app.ECoGArrayCheckBox.Value)

                else
                    if str2double(app.NrChannelEditField.Value)*2 >=64
                        app.FirstZoomChannel = str2double(app.NrChannelEditField.Value)-31;
                    else
                        app.FirstZoomChannel = (str2double(app.NrChannelEditField.Value)-1)-(str2double(app.NrChannelEditField.Value));
                    end 

                    if isempty(app.ActiveChannelField.Value{1})
                        ActiveChannel = 1:str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value);
                    else
                        ActiveChannel = str2double(strsplit(app.ActiveChannelField.Value{1},','));
                        if length(unique(ActiveChannel)) ~= length(ActiveChannel)
                            msgbox(strcat("Format error: ",num2str(length(ActiveChannel)-length(unique(ActiveChannel)))," Channel is/are present more than once. Please retry!"));
                            return;
                        end
                    end

                    if app.ReverseTopandBottomChannelNumberCheckBox.Value == 1
                        ActiveChannel = (str2double(app.NrChannelEditField.Value)*str2double(app.ChannelRowsDropDown.Value)+1)-ActiveChannel;
                        oddIndices = find(mod(ActiveChannel, 2) == 1);
                        evenIndices = find(mod(ActiveChannel, 2) == 0);
                        ActiveChannel(oddIndices) = ActiveChannel(oddIndices)+1;
                        ActiveChannel(evenIndices) = ActiveChannel(evenIndices)-1;
                    end

                    if isfield(app.ProbeTrajectoryInfo,'AreaNamesLong')
                        BrainAreaInfo = app.ProbeTrajectoryInfo;
                    else
                        BrainAreaInfo = [];
                    end

                    %app.FirstZoomChannel = str2double(app.NrChannelEditField.Value);
                    Utility_Plot_Interactive_Probe_View(app.UIAxes,str2double(app.ChannelSpacingumEditField.Value),str2double(app.NrChannelEditField.Value),str2double(app.ChannelRowsDropDown.Value),str2double(app.HorizontalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField.Value),str2double(app.VerticalOffsetumEditField_2.Value),ActiveChannel,app.FirstZoomChannel,1,BrainAreaInfo,ActiveChannel,app.ShowChannelSpacingCheckBox.Value,1,1,[],app.CheckBox.Value,[],app.ReverseTopandBottomChannelNumberCheckBox.Value,app.SwitchLeftandRightChannelNumberCheckBox.Value,app.ECoGArrayCheckBox.Value)
                end


                %% Initiate Callback
                if isempty(app.UIAxes.ButtonDownFcn)
                    app.UIAxes.ButtonDownFcn = @(src1, event1) ProbeViewClickCallback(app, event1);
                end    
            
                % Add ButtonDownFcn to each line object in UIAxis
                lines = findobj(app.UIAxes, 'Type', 'line');
                
                % Add ButtonDownFcn to each line object in UIAxis
                ChannelViewRight = findobj(app.UIAxes,'Tag','ChannelViewRight');
            
                % Add ButtonDownFcn to each line object in UIAxis
                ChannelViewLeft = findobj(app.UIAxes,'Tag','ChannelViewLeft');
            
                % Add ButtonDownFcn to each line object in UIAxis
                GrayProbeFilling = findobj(app.UIAxes,'Tag','GrayProbeFilling');
                    
                %% Set the ButtonDownFcn for UIAxes to register clicks on a plotted line directly
                % lines plotted
                for i = 1:numel(lines)
                    % Call Lineclicked function if that happens
                    lines(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
            
                % Channel squares on the right
                for i = 1:numel(ChannelViewRight)
                    % Call Lineclicked function if that happens
                    ChannelViewRight(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                % Channel squares on the left
                for i = 1:numel(ChannelViewLeft)
                    % Call Lineclicked function if that happens
                    ChannelViewLeft(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end
                % Channel squares on the left
                for i = 1:numel(GrayProbeFilling)
                    % Call Lineclicked function if that happens
                    GrayProbeFilling(i).ButtonDownFcn = @(src1, event1) ProbeViewClickLineCallback(app, event1);
                end

                %% Create Legend
                % Create a dummy red line plot for the legend
                dummyLine = plot(app.UIAxes2, NaN, NaN, 'Color', 'red', 'LineWidth', 2);
                
                % Create a dummy yellow rectangle using a patch for the legend
                dummyRect = patch(app.UIAxes2, NaN, NaN, 'yellow', 'EdgeColor', 'none');
                
                % Create the legend and position it at the center of the plot
                legend(app.UIAxes2, [dummyLine, dummyRect], {'All Active Channel', 'Currently Active Channel'}, 'AutoUpdate', 'off');
                
                % Position the legend in the middle of the figure (over the entire figure size)
                legendPosition = [0.6, 0.9, 0.15, 0.04]; % x, y, width, height (normalized)
                set(app.UIAxes2.Legend, 'Position', legendPosition);
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ProbeLayoutWindowUIFigure = uifigure('Visible', 'off');
            app.ProbeLayoutWindowUIFigure.Name = 'Probe Layout Window';
            app.ProbeLayoutWindowUIFigure.Icon = 'Logo.png';
            app.ProbeLayoutWindowUIFigure.CloseRequestFcn = createCallbackFcn(app, @ProbeLayoutWindowUIFigureCloseRequest, true);
            app.SaveProbeLayoutMenu = uimenu(app.ProbeLayoutWindowUIFigure);
            app.SaveProbeLayoutMenu.Text = '   Save Probe Layout   ';
            app.SaveProbeInfoforKilosortMenu = uimenu(app.SaveProbeLayoutMenu);
            app.SaveProbeInfoforKilosortMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveProbeInfoforKilosortMenuSelected, true);
            app.SaveProbeInfoforKilosortMenu.Text = 'Save Probe Info for Kilosort';
            app.SaveProbeInfoforGUIMenu = uimenu(app.SaveProbeLayoutMenu);
            app.SaveProbeInfoforGUIMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveProbeInfoforGUIMenuSelected, true);
            app.SaveProbeInfoforGUIMenu.Text = 'Save Probe Info for GUI';
            app.SaveChannelOrderMenu = uimenu(app.SaveProbeLayoutMenu);
            app.SaveChannelOrderMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveChannelOrderMenuSelected, true);
            app.SaveChannelOrderMenu.Text = 'Save Channel Order';
            app.SaveActiveChannelMenu = uimenu(app.SaveProbeLayoutMenu);
            app.SaveActiveChannelMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveActiveChannelMenuSelected, true);
            app.SaveActiveChannelMenu.Text = 'Save Active Channel';
            app.TrajectoryExplorerMenu = uimenu(app.ProbeLayoutWindowUIFigure);
            app.TrajectoryExplorerMenu.Text = '   Trajectory Explorer   ';
            app.Menu = uimenu(app.TrajectoryExplorerMenu);
            app.Menu.MenuSelectedFcn = createCallbackFcn(app, @MenuSelected, true);
            app.Menu.Text = 'Start Trajectory Explorer (requires to download Allen mouse brain atlas)';
            app.LoadSavedTrajectoryMenu = uimenu(app.TrajectoryExplorerMenu);
            app.LoadSavedTrajectoryMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadSavedTrajectoryMenuSelected, true);
            app.LoadSavedTrajectoryMenu.Text = 'Load Saved Trajectory';
            app.UIAxes = uiaxes(app.ProbeLayoutWindowUIFigure);
            app.UIAxes.XTickLabel = '';
            app.UIAxes2 = uiaxes(app.ProbeLayoutWindowUIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.ZTick = [];
            app.SetChannelInformationPanel = uipanel(app.ProbeLayoutWindowUIFigure);
            app.SetChannelInformationPanel.Title = 'Set Channel Information';
            app.ChannelOrderFormat1234LeaveemptyfornocostumorderLabel = uilabel(app.SetChannelInformationPanel);
            app.ChannelOrderFormat1234LeaveemptyfornocostumorderLabel.Text = {'Format: 1,2,3,4'; 'Leave empty for no costum order'};
            app.ChannelOrderField = uitextarea(app.SetChannelInformationPanel);
            app.ChannelOrderField.ValueChangedFcn = createCallbackFcn(app, @ChannelOrderFieldValueChanged, true);
            app.ChannelOrderFormat1234LeaveemptyfornocostumorderLabel_2 = uilabel(app.SetChannelInformationPanel);
            app.ChannelOrderFormat1234LeaveemptyfornocostumorderLabel_2.Text = {'Format: 1,2,3,4'; 'Leave empty for all channel '; 'being active'};
            app.ActiveChannelField = uitextarea(app.SetChannelInformationPanel);
            app.ActiveChannelField.ValueChangedFcn = createCallbackFcn(app, @ActiveChannelFieldValueChanged, true);
            app.LoadActiveChannelSelectionButton = uibutton(app.SetChannelInformationPanel, 'push');
            app.LoadActiveChannelSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @LoadActiveChannelSelectionButtonPushed, true);
            app.LoadActiveChannelSelectionButton.Text = {'Load Active'; 'Channel Selection'};
            app.LoadChannelOrderButton = uibutton(app.SetChannelInformationPanel, 'push');
            app.LoadChannelOrderButton.ButtonPushedFcn = createCallbackFcn(app, @LoadChannelOrderButtonPushed, true);
            app.LoadChannelOrderButton.Text = {'Load Channel '; 'Order'};
            app.ChannelOrderjustforactiveChannelLabel = uilabel(app.SetChannelInformationPanel);
            app.ChannelOrderjustforactiveChannelLabel.Text = {'Channel Order'; '(just for active Channel!)'};
            app.ActiveChannelLabel_2 = uilabel(app.SetChannelInformationPanel);
            app.ActiveChannelLabel_2.Text = 'Active Channel';
            app.SetChannelInformationLabel = uilabel(app.SetChannelInformationPanel);
            app.SetChannelInformationLabel.Text = 'Set Channel Information';
            app.InteractiveProbeViewLabel = uilabel(app.ProbeLayoutWindowUIFigure);
            app.InteractiveProbeViewLabel.Text = 'Interactive Probe View';
            app.CloseButton = uibutton(app.ProbeLayoutWindowUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed2, true);
            app.CloseButton.Text = 'Close';
            app.SetProbeGeometryPanel = uipanel(app.ProbeLayoutWindowUIFigure);
            app.SetProbeGeometryPanel.Title = 'Set Probe Geometry';
            app.ChannelSpacingumLabel = uilabel(app.SetProbeGeometryPanel);
            app.ChannelSpacingumLabel.Text = {'Channel Spacing '; '[µm]'};
            app.ChannelSpacingumEditField = uieditfield(app.SetProbeGeometryPanel, 'text');
            app.ChannelSpacingumEditField.ValueChangedFcn = createCallbackFcn(app, @ChannelSpacingumEditFieldValueChanged, true);
            app.ChannelSpacingumEditField.ValueChangingFcn = createCallbackFcn(app, @ChannelSpacingumEditFieldValueChanging, true);
            app.VerticalOffsetBetweenChannelRowsumLabel = uilabel(app.SetProbeGeometryPanel);
            app.VerticalOffsetBetweenChannelRowsumLabel.Text = {'Vertical Offset'; 'Between Channel Rows [µm]'};
            app.VerticalOffsetumEditField = uieditfield(app.SetProbeGeometryPanel, 'text');
            app.VerticalOffsetumEditField.ValueChangedFcn = createCallbackFcn(app, @VerticalOffsetumEditFieldValueChanged, true);
            app.VerticalOffsetumEditField.Enable = 'off';
            app.VerticalOffsetumEditField.Value = '0';
            app.NrChannelperRowEditFieldLabel = uilabel(app.SetProbeGeometryPanel);
            app.NrChannelperRowEditFieldLabel.Text = {'Nr Channel'; 'per Row'};
            app.NrChannelEditField = uieditfield(app.SetProbeGeometryPanel, 'text');
            app.NrChannelEditField.ValueChangedFcn = createCallbackFcn(app, @NrChannelEditFieldValueChanged, true);
            app.NrChannelEditField.ValueChangingFcn = createCallbackFcn(app, @NrChannelEditFieldValueChanging, true);
            app.HorizontalOffsetBetweenChannelRowsmLabel = uilabel(app.SetProbeGeometryPanel);
            app.HorizontalOffsetBetweenChannelRowsmLabel.Text = {'Horizontal Offset'; 'Between Channel Rows [µm]'};
            app.HorizontalOffsetumEditField = uieditfield(app.SetProbeGeometryPanel, 'text');
            app.HorizontalOffsetumEditField.ValueChangedFcn = createCallbackFcn(app, @HorizontalOffsetumEditFieldValueChanged, true);
            app.HorizontalOffsetumEditField.Enable = 'off';
            app.HorizontalOffsetumEditField.Value = '0';
            app.VerticalOffsetBetweenChannelRowsumLabel_2 = uilabel(app.SetProbeGeometryPanel);
            app.VerticalOffsetBetweenChannelRowsumLabel_2.Text = {'Vertical Offset Between'; 'Every Second Row [µm]'};
            app.VerticalOffsetumEditField_2 = uieditfield(app.SetProbeGeometryPanel, 'text');
            app.VerticalOffsetumEditField_2.ValueChangedFcn = createCallbackFcn(app, @VerticalOffsetumEditField_2ValueChanged, true);
            app.VerticalOffsetumEditField_2.Value = '0';
            app.CheckBox = uicheckbox(app.SetProbeGeometryPanel);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.ChannelSpacingumLabel_2 = uilabel(app.SetProbeGeometryPanel);
            app.ChannelSpacingumLabel_2.Text = 'Channel Rows';
            app.ChannelRowsDropDown = uieditfield(app.SetProbeGeometryPanel, 'text');
            app.ChannelRowsDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelRowsDropDownValueChanged2, true);
            app.ChannelRowsDropDown.Value = '1';
            app.SetProbeInformationandContinueButton = uibutton(app.ProbeLayoutWindowUIFigure, 'push');
            app.SetProbeInformationandContinueButton.ButtonPushedFcn = createCallbackFcn(app, @SetProbeInformationandContinueButtonPushed, true);
            app.SetProbeInformationandContinueButton.Text = {'Set Probe Information '; 'and Continue'};
            app.ShowChannelSpacingCheckBox = uicheckbox(app.ProbeLayoutWindowUIFigure);
            app.ShowChannelSpacingCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowChannelSpacingCheckBoxValueChanged, true);
            app.ShowChannelSpacingCheckBox.Text = '    Show Channel Spacing';
            app.AdditionalOptionsPanel = uipanel(app.ProbeLayoutWindowUIFigure);
            app.AdditionalOptionsPanel.Title = 'Additional Options';
            app.ReverseTopandBottomChannelNumberCheckBox = uicheckbox(app.AdditionalOptionsPanel);
            app.ReverseTopandBottomChannelNumberCheckBox.ValueChangedFcn = createCallbackFcn(app, @ReverseTopandBottomChannelNumberCheckBoxValueChanged, true);
            app.ReverseTopandBottomChannelNumberCheckBox.Text = {'   Reverse Top and Bottom'; '   Channel Number Names'};
            app.ReverseTopandBottomChannelNumberCheckBox_2 = uicheckbox(app.AdditionalOptionsPanel);
            app.ReverseTopandBottomChannelNumberCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @ReverseTopandBottomChannelNumberCheckBox_2ValueChanged, true);
            app.ReverseTopandBottomChannelNumberCheckBox_2.Text = {'   Flip loaded Data (after'; '   applying channel order)'};
            app.SwitchLeftandRightChannelNumberCheckBox = uicheckbox(app.AdditionalOptionsPanel);
            app.SwitchLeftandRightChannelNumberCheckBox.ValueChangedFcn = createCallbackFcn(app, @SwitchLeftandRightChannelNumberCheckBoxValueChanged, true);
            app.SwitchLeftandRightChannelNumberCheckBox.Visible = 'off';
            app.SwitchLeftandRightChannelNumberCheckBox.Text = {'   Switch Left and Right'; '   Channel Number Names'};
            app.AdditionalOptionsLabel = uilabel(app.AdditionalOptionsPanel);
            app.AdditionalOptionsLabel.Text = 'Additional Options';
            app.ECoGArrayCheckBox = uicheckbox(app.AdditionalOptionsPanel);
            app.ECoGArrayCheckBox.ValueChangedFcn = createCallbackFcn(app, @ECoGArrayCheckBoxValueChanged, true);
            app.ECoGArrayCheckBox.Visible = 'off';
            app.ECoGArrayCheckBox.Enable = 'off';
            app.ECoGArrayCheckBox.Text = '   ECoG Array';
            app.SetProbeGeometryLabel = uilabel(app.ProbeLayoutWindowUIFigure);
            app.SetProbeGeometryLabel.Text = 'Set Probe Geometry';
            % Show the figure after all components are created
            app.ProbeLayoutWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Probe_Layout_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ProbeLayoutWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ProbeLayoutWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ProbeLayoutWindowUIFigure)
        end
    end
end