classdef Load_Data_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LoadDataWindowUIFigure       matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        LoadingOptionsPanel          matlab.ui.container.Panel
        FormatToLoadLabel            matlab.ui.control.Label
        DropDown_3                   matlab.ui.control.DropDown
        LoadingOptionsLabel          matlab.ui.control.Label
        SaveFileSelectionLabel       matlab.ui.control.Label
        LoadButton                   matlab.ui.control.Button
        DropDown_2                   matlab.ui.control.DropDown
        SelectDifferentFolderButton  matlab.ui.control.Button
        RightPanel                   matlab.ui.container.Panel
        DropDown                     matlab.ui.control.DropDown
        DataFormatSelectionLabel     matlab.ui.control.Label
        CloseButton                  matlab.ui.control.Button
        InformationLabel             matlab.ui.control.Label
        TextArea                     matlab.ui.control.TextArea
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Mainapp = []; % Description
        SelectedFolder = [];
        DataMatfileindex = [];
        DataDATfileindex = [];
        Infofileindex = [];
        InfoFiletoLoad = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            % Moce this window in the middle of thescreen
            movegui(app.LoadDataWindowUIFigure, 'center');

            app.Mainapp = Mainapp;
            
            app = Utility_Change_Light_Dark_Mode(app,"Load_Data_Window");

            %% Analyze Folder components (standard folder = GUI Path/SavedData)
            [app.SelectedFolder,app.DataMatfileindex,app.DataDATfileindex,app.Infofileindex,app.InfoFiletoLoad,Formatsfound,DropDownMenuChar,DropDownMenu_2Char,stringArray] = Manage_Dataset_Module_LoadGUIDataCheckFolderContents(app.Mainapp.executableFolder,[]);
            
            if isempty(Formatsfound)
                TextToShow = "Load one of the formats possible to save in the 'Save Dataset' window. **NOTE** If you want to load data that was saved by the python script running NEO to extract a raw recording without having to extract again using NEO, use the 'Load Raw Recordings' window, select NEO and activate the checkbox to load a recording. Here you can only load the .mat file created to be readable for NEO without having to extract a proprietary recording format with it.";"";"Saved data is automatically searched for in the folder 'Path_to_NeuroMod/Recording Data/Saved GUI Data' and selectable in the dropdown menu when found. Alternatively or if you want to load the data saved when extracting a recording with NEO, select the folder you manually saved data in. The folder containing saved NEO files for an already extracted recording is in the same path than the recording folder called 'Recordingfoldername_Neo SaveFile'. It is autocreated when using NEO.";
                TextToShow = [TextToShow;strcat("Autosearched Folder ",app.SelectedFolder," contains no valid .dat or .mat files.")];
                TextToShow = [TextToShow;"Please select a folder with saved files manually. Saving data as .dat file also saves a Info.mat file that has to be present (and match the name of the .dat file with an 'Info' at the end) to be detected as a valid save file."];
                app.TextArea.Value = [TextToShow;"";"If proper save files are found in the selected folder, you will see the folder contents in this text area and can select the save file name on the left."];
                return;
            end

            %% Show folder components in GUI
            app.DropDown.Items = DropDownMenuChar;
            app.DropDown_2.Items = DropDownMenu_2Char;
            
            TextToShow = "Load one of the formats possible to save in the 'Save Dataset' window. **NOTE** If you want to load data that was saved by the python script running NEO to extract a raw recording without having to extract again using NEO, use the 'Load Raw Recordings' window, select NEO and activate the checkbox to load a recording. Here you can only load the .mat file created to be readable for NEO without having to extract a proprietary recording format with it.";"";"Saved data is automatically searched for in the folder 'Path_to_NeuroMod/Recording Data/Saved GUI Data' and selectable in the dropdown menu when found. Alternatively or if you want to load the data saved when extracting a recording with NEO, select the folder you manually saved data in. The folder containing saved NEO files for an already extracted recording is in the same path than the recording folder called 'Recordingfoldername_Neo SaveFile'. It is autocreated when using NEO.";
            TextToShow = [TextToShow;"";"Auto-searched path:";"";app.SelectedFolder];
            app.TextArea.Value = [TextToShow;"";"Saved GUI Data was detected";"";"Folder Conents:";stringArray];
            
            try
                if strcmp(app.DropDown_2.Value(end-3:end),".mat")
                    File = app.DropDown_2.Value;
                    FullPathData = fullfile(app.SelectedFolder,File);
        
                    %% Check if selected mat file contains correct variable
                    variableName = 'Raw';  % Variable you want to load
        
                    % Get the list of variables in the file
                    variablesInFile = who('-file', FullPathData);
        
                    DataType = 0;
        
                    % Check if the desired variable exists
                    if ismember(variableName, variablesInFile)
                        DataType = 1; % NeuroMod Interna Data
                    else
                        DataType = 2; % Neo .mat file Interna Data
                    end
        
                    if DataType == 1
                        app.DropDown_3.Value = 'Saved NeuroMod format';
                    elseif DataType == 2
                        app.DropDown_3.Value = 'Saved Neo readable .mat file';
                    end
                    
                elseif strcmp(app.DropDown_2.Value(end-3:end),".dat")
                    DataType = 1; % NeuroMod Interna Data
                    app.DropDown_3.Value = 'Saved NeuroMod format';
                    
                elseif strcmp(app.DropDown_2.Value(end-3:end),".bin")
                    DataType = 2; % NeuroMod Interna Data
                    app.DropDown_3.Value = 'Saved SpikeInterface format';
                elseif strcmp(app.DropDown_2.Value(end-3:end),".nwb")
                    DataType = 3; % NeuroMod Interna Data
                    app.DropDown_3.Value = 'Saved NWB format';
                end
            end
        end

        % Button pushed function: SelectDifferentFolderButton
        function SelectDifferentFolderButtonPushed(app, event)
            %% Get user selcted folder and save as app.Load_Data_Window_Info.selectedFolder
            % Prompt the user to select a folder
            
            PathToOpen = strcat(app.Mainapp.executableFolder,'\Recording Data\Saved GUI Data\');

            app.SelectedFolder = [];
            app.DataMatfileindex = [];
            app.DataDATfileindex = [];
            app.Infofileindex = [];
            app.InfoFiletoLoad = [];

            Placeholder = {};
            app.DropDown_2.Items = Placeholder;
            
            if isfolder(PathToOpen)
                NewFolderSelection = uigetdir(PathToOpen,'Select a folder with your recording data');
            else
                NewFolderSelection = uigetdir('Select a folder with your recording data');
            end

            if NewFolderSelection == 0
                disp('No folder selected. Exiting.');
                app.TextArea.Value = 'No folder selected or no suitable data format found within folder.';
                return;
            end
            
            %% Analyze Folder components (standard folder = GUI Path/SavedData)
            [app.SelectedFolder,app.DataMatfileindex,app.DataDATfileindex,app.Infofileindex,app.InfoFiletoLoad,Formatsfound,DropDownMenuChar,DropDownMenu_2Char,stringArray] = Manage_Dataset_Module_LoadGUIDataCheckFolderContents(app.Mainapp.executableFolder,NewFolderSelection);

            if isempty(app.DataDATfileindex) && isempty(app.DataMatfileindex)
                stringtoshow = strcat("Warning: No supported file types found in ",app.SelectedFolder);
                stringtoshow = [stringtoshow; " Make sure the folder contains valid .mat or .dat files saved with this GUI."];
                app.TextArea.Value = stringtoshow;
                %msgbox(stringtoshow);
                return;
            end

            %% Show folder components in GUI
            if ~isempty(DropDownMenuChar{1})
                app.DropDown.Items = DropDownMenuChar;
            end
            if ~isempty(DropDownMenu_2Char{1})
                app.DropDown_2.Items = DropDownMenu_2Char;
            end

            try
                if strcmp(app.DropDown_2.Value(end-3:end),".mat")
                    File = app.DropDown_2.Value;
                    FullPathData = fullfile(app.SelectedFolder,File);
        
                    %% Check if selected mat file contains correct variable
                    variableName = 'Raw';  % Variable you want to load
        
                    % Get the list of variables in the file
                    variablesInFile = who('-file', FullPathData);
        
                    DataType = 0;
        
                    % Check if the desired variable exists
                    if ismember(variableName, variablesInFile)
                        DataType = 1; % NeuroMod Interna Data
                    else
                        DataType = 2; % Neo .mat file Interna Data
                    end
        
                    if DataType == 1
                        app.DropDown_3.Value = 'Saved NeuroMod format';
                    elseif DataType == 2
                        app.DropDown_3.Value = 'Saved Neo readable .mat file';
                    end
                elseif strcmp(app.DropDown_2.Value(end-3:end),".dat")
                    DataType = 1; % NeuroMod Interna Data
                    app.DropDown_3.Value = 'Saved NeuroMod format';
                elseif strcmp(app.DropDown_2.Value(end-3:end),".bin")
                    DataType = 2; % NeuroMod Interna Data
                    app.DropDown_3.Value = 'Saved SpikeInterface format';
                elseif strcmp(app.DropDown_2.Value(end-3:end),".nwb")
                    DataType = 3; % NeuroMod Interna Data
                    app.DropDown_3.Value = 'Saved NWB format';
                end
            end
            
            app.TextArea.Value = ["Path:";"";app.SelectedFolder];
            app.TextArea.Value = [app.TextArea.Value;"";"Folder Contents:";"";stringArray];
            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            
            if isempty(app.DropDown_2.Value)
                msgbox("Error: no valid file selected!")
                return;
            end

            Format = app.DropDown.Value;
            File = app.DropDown_2.Value;
            FullPathData = fullfile(app.SelectedFolder,File);
            if ~isempty(app.InfoFiletoLoad)
                FullPathInfo = fullfile(app.SelectedFolder,app.InfoFiletoLoad);
            else
                FullPathInfo = [];
            end

            if isempty(FullPathInfo) && ~strcmp(app.DropDown.Value,".mat") && strcmp(app.DropDown_3.Value,"NeuroMod format")
                f = msgbox("No Info file found with the same name as data file +_Info. Returning");
                return;
            end

            [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Initial");
            
            if strcmp(app.DropDown_3.Value,"Saved NeuroMod format")
                [app.Mainapp.Data] = Manage_Dataset_Module_Load_NeuroModData(Format,FullPathData,FullPathInfo,app.TextArea);
            elseif strcmp(app.DropDown_3.Value,"Saved Neo readable .mat file")
                File = app.DropDown_2.Value;
                dotindex = find(app.DropDown_2.Value=='.');
                InfoFile = strcat(app.DropDown_2.Value(1:dotindex(end)-1),'_Info.mat');
                
                FullDataPath = fullfile(app.SelectedFolder,File);
                FullInfoPath = fullfile(app.SelectedFolder,InfoFile);

                [app.Mainapp.Data,Textbox] = Manage_Dataset_Module_Load_NeoMatData(FullDataPath,FullInfoPath);
                if isempty(app.Mainapp.Data)
                    app.TextArea.Value = Textbox;
                end

            elseif strcmp(app.DropDown_3.Value,"Saved NWB format")
                
                [app.Mainapp.Data] = Manage_Dataset_Module_Load_NWBFile(FullPathData);

            elseif strcmp(app.DropDown_3.Value,"Saved SpikeInterface format")

                [app.Mainapp.Data] = Manage_Dataset_Module_Load_SpikeInterface(FullPathData);

            end

            if ~isempty(app.Mainapp.Data)
                [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Loading");
            else
                return;
            end
            
            Organize_Delete_All_Open_Windows(app.Mainapp,1);

            KeepSpikes = 0;
            KeepEvents = 0;
            if isfield(app.Mainapp.Data,'Spikes') && strcmp(app.Mainapp.Plotspikes,"Spikes")
                KeepSpikes = 1;
            end
            if isfield(app.Mainapp.Data,'Events') && strcmp(app.Mainapp.PlotEvents,"Events")
                KeepEvents = 1;
            end

            Organize_Reset_Main_Plot(app.Mainapp,0,KeepEvents,KeepSpikes,0,app.Mainapp.CurrentTimePoints);
            

            app.Mainapp.ProbeViewWindowHandle = Probe_View_Window(app.Mainapp,'MainWindow');

            [app.Mainapp] = Utility_Set_ToolTips(app.Mainapp,app.Mainapp.ShowToolTipsSetting,"ProbeViewMainWindow");

            app.Mainapp = Utility_Change_Light_Dark_Mode(app.Mainapp,"MainWindow");
            
            delete(app);

        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            try
                app.Mainapp.LoadDataWindow = [];
            end
            delete(app);
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            %% When data format is changed, filenames that have to be displayed change. If changed to .mat files, all .mat files have to be displayed
            value = app.DropDown.Value;
            Placeholder = {};

            app.DropDown_2.Items = Placeholder;

            [stringArray] = Utility_Extract_Contents_of_Folder(app.SelectedFolder);

            Indextodelete = [];
            for i = 1:length(stringArray)
                if ~strcmp(stringArray(i),"")
                    if ~contains(stringArray(i),'.')
                        Indextodelete = [Indextodelete,i];
                    end
                end
            end
            if ~isempty(Indextodelete)
                stringArray(Indextodelete) = [];
            end

            if strcmp(value,".mat")

                if ~isempty(app.DataMatfileindex)
                    for i = 1:length(app.DataMatfileindex)
                        app.DropDown_2.Items{i} = convertStringsToChars(stringArray(app.DataMatfileindex(i)));
                    end
                end

            elseif strcmp(value,".dat")
    
                if ~isempty(app.DataDATfileindex)
                    for i = 1:length(app.DataDATfileindex)
                        app.DropDown_2.Items{i} = convertStringsToChars(stringArray(app.DataDATfileindex(i)));
                    end
                end
            end

            if ~strcmp(app.DropDown_2.Value(end-3:end),".mat")
                for i = 1:length(app.Infofileindex)
                    Infofilename = convertStringsToChars(stringArray(app.Infofileindex(i)));
                    if strcmp(Infofilename(1:end-9),app.DropDown_2.Value(1:end-4))
                        app.InfoFiletoLoad = Infofilename;
                    end
                end
            end
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            %% When new file is selected and it is a .dat file, update the corresponding Info file (app.InfoFiletoLoad)
            
            app.InfoFiletoLoad = [];

            [stringArray] = Utility_Extract_Contents_of_Folder(app.SelectedFolder);

            Indextodelete = [];
            for i = 1:length(stringArray)
                if ~strcmp(stringArray(i),"")
                    if ~contains(stringArray(i),'.')
                        Indextodelete = [Indextodelete,i];
                    end
                end
            end
            if ~isempty(Indextodelete)
                stringArray(Indextodelete) = [];
            end

            if ~strcmp(app.DropDown_2.Value(end-3:end),".mat")
                for i = 1:length(app.Infofileindex)
                    Infofilename = convertStringsToChars(stringArray(app.Infofileindex(i)));
                    if length(Infofilename) >= 10
                        if strcmp(Infofilename(1:end-9),app.DropDown_2.Value(1:end-4))
                            app.InfoFiletoLoad = Infofilename;
                        end
                    end
                end

            end
            
            try
                if strcmp(app.DropDown_2.Value(end-3:end),".mat")
                    File = app.DropDown_2.Value;
                    FullPathData = fullfile(app.SelectedFolder,File);
        
                    %% Check if selected mat file contains correct variable
                    variableName = 'Raw';  % Variable you want to load
        
                    % Get the list of variables in the file
                    variablesInFile = who('-file', FullPathData);
        
                    DataType = 0;
        
                    % Check if the desired variable exists
                    if ismember(variableName, variablesInFile)
                        DataType = 1; % NeuroMod Interna Data
                    else
                        DataType = 2; % Neo .mat file Interna Data
                    end
        
                    if DataType == 1
                        app.DropDown_3.Value = 'Saved NeuroMod format';
                    elseif DataType == 2
                        app.DropDown_3.Value = 'Saved Neo readable .mat file';
                    end
                elseif strcmp(app.DropDown_2.Value(end-3:end),".dat")
                    DataType = 1; % NeuroMod Interna Data
                    app.DropDown_3.Value = 'Saved NeuroMod format';
                elseif strcmp(app.DropDown_2.Value(end-3:end),".bin")
                    DataType = 2; % NeuroMod Interna Data
                    app.DropDown_3.Value = 'Saved SpikeInterface format';
                elseif strcmp(app.DropDown_2.Value(end-3:end),".nwb")
                    DataType = 3; % NeuroMod Interna Data
                    app.DropDown_3.Value = 'Saved NWB format';
                end
            end
        end

        % Close request function: LoadDataWindowUIFigure
        function LoadDataWindowUIFigureCloseRequest(app, event)
            try
                app.Mainapp.LoadDataWindow = [];
            end
            delete(app)
            
        end

        % Value changed function: DropDown_3
        function DropDown_3ValueChanged(app, event)
            value = app.DropDown_3.Value;

            [~,~,~,~,~,Formatsfound,DropDownMenuChar,DropDownMenu_2Char,stringArray] = Manage_Dataset_Module_LoadGUIDataCheckFolderContents(app.Mainapp.executableFolder,app.SelectedFolder);
            
            if ~isempty(Formatsfound)
                if strcmp(value,"Saved NeuroMod format")
                    if ~isempty(find(Formatsfound==".mat'")) || ~isempty(find(Formatsfound==".dat'"))
                        for i = 1:length(DropDownMenu_2Char)
                            if strcmp(DropDownMenu_2Char{i}(end-3:end),'.dat')
                                app.DropDown_2.Value = app.DropDown_2.Items{i};
                                return
                            elseif strcmp(DropDownMenu_2Char{i}(end-3:end),'.mat')
                                File = DropDownMenu_2Char{i};
                                FullPathData = fullfile(app.SelectedFolder,File);
                    
                                %% Check if selected mat file contains correct variable
                                variableName = 'Raw';  % Variable you want to load
                    
                                % Get the list of variables in the file
                                variablesInFile = who('-file', FullPathData);
    
                                if ismember(variableName, variablesInFile) % if is MatlabInternal
                                    app.DropDown_2.Value = app.DropDown_2.Items{i};
                                    return
                                end
                            end
                        end
                    end
                    
                elseif strcmp(value,"Saved SpikeInterface format")
                    if ~isempty(find(Formatsfound==".bin'")) 
                        for i = 1:length(DropDownMenu_2Char)
                            if strcmp(DropDownMenu_2Char{i}(end-3:end),'.bin')
                                app.DropDown_2.Value = app.DropDown_2.Items{i};
                                return
                            end
                        end
                    end
                    
                elseif strcmp(value,"Saved NWB format")
                    if ~isempty(find(Formatsfound==".nwb'")) 
                        for i = 1:length(DropDownMenu_2Char)
                            if strcmp(DropDownMenu_2Char{i}(end-3:end),'.nwb')
                                app.DropDown_2.Value = app.DropDown_2.Items{i};
                                return
                            end
                        end
                    end
                elseif strcmp(value,"Saved Neo readable .mat file")
                    if ~isempty(find(Formatsfound==".mat'")) 
                        for i = 1:length(DropDownMenu_2Char)
                            if strcmp(DropDownMenu_2Char{i}(end-3:end),".mat")
                                File = DropDownMenu_2Char{i};
                                FullPathData = fullfile(app.SelectedFolder,File);
                    
                                %% Check if selected mat file contains correct variable
                                variableName = 'block';  % Variable you want to load
                    
                                % Get the list of variables in the file
                                variablesInFile = who('-file', FullPathData);
                    
                                % Check if the desired variable exists
                                if ismember(variableName, variablesInFile) % if is neo
                                    app.DropDown_2.Value = app.DropDown_2.Items{i};
                                    return
                                end
                            end
                        end
                    end
                end
            end
            % if no return was hit:
            msgbox("No file with the selected format found within currently searched folder.")
            app.DropDown_3.Value = event.PreviousValue;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.LoadDataWindowUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {392, 392};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {260, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LoadDataWindowUIFigure = uifigure('Visible', 'off');
            app.LoadDataWindowUIFigure.Name = 'Load Data Window';
            app.LoadDataWindowUIFigure.Icon = 'Logo.png';
            app.LoadDataWindowUIFigure.CloseRequestFcn = createCallbackFcn(app, @LoadDataWindowUIFigureCloseRequest, true);
            app.LoadDataWindowUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.LoadDataWindowUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LoadingOptionsPanel = uipanel(app.LeftPanel);
            app.LoadingOptionsPanel.Title = 'Loading Options';
            app.SelectDifferentFolderButton = uibutton(app.LoadingOptionsPanel, 'push');
            app.SelectDifferentFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDifferentFolderButtonPushed, true);
            app.SelectDifferentFolderButton.Text = {'Select Different '; 'Folder'};
            app.DropDown_2 = uidropdown(app.LoadingOptionsPanel);
            app.DropDown_2.Items = {};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = {};
            app.LoadButton = uibutton(app.LoadingOptionsPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SaveFileSelectionLabel = uilabel(app.LoadingOptionsPanel);
            app.SaveFileSelectionLabel.Text = 'Save File Selection';
            app.LoadingOptionsLabel = uilabel(app.LoadingOptionsPanel);
            app.LoadingOptionsLabel.Text = 'Loading Options';
            app.DropDown_3 = uidropdown(app.LoadingOptionsPanel);
            app.DropDown_3.Items = {'Saved NeuroMod format', 'Saved Neo readable .mat file', 'Saved NWB format', 'Saved SpikeInterface format'};
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_3ValueChanged, true);
            app.DropDown_3.Value = 'Saved NeuroMod format';
            app.FormatToLoadLabel = uilabel(app.LoadingOptionsPanel);
            app.FormatToLoadLabel.Text = 'Format To Load';
            app.RightPanel = uipanel(app.GridLayout);
            app.TextArea = uitextarea(app.RightPanel);
            app.TextArea.Editable = 'off';
            app.InformationLabel = uilabel(app.RightPanel);
            app.InformationLabel.Text = 'Information';
            app.CloseButton = uibutton(app.RightPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.DataFormatSelectionLabel = uilabel(app.RightPanel);
            app.DataFormatSelectionLabel.Enable = 'off';
            app.DataFormatSelectionLabel.Visible = 'off';
            app.DataFormatSelectionLabel.Text = 'Data Format Selection';
            app.DropDown = uidropdown(app.RightPanel);
            app.DropDown.Items = {};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Enable = 'off';
            app.DropDown.Visible = 'off';
            app.DropDown.Value = {};
            % Show the figure after all components are created
            app.LoadDataWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Load_Data_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.LoadDataWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.LoadDataWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LoadDataWindowUIFigure)
        end
    end
end