classdef Save_Data_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SaveDataWindowUIFigure  matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        SaveOptionsPanel        matlab.ui.container.Panel
        OpenTrialDatainFieldTripDataBrowserCheckBox  matlab.ui.control.CheckBox
        OpenRawTraceinFieldTripDataBrowserCheckBox  matlab.ui.control.CheckBox
        SaveTypeDropDown_2      matlab.ui.control.DropDown
        SaveforLabel            matlab.ui.control.Label
        SaveOptionsLabel        matlab.ui.control.Label
        SaveButton              matlab.ui.control.Button
        SelectAllButton         matlab.ui.control.StateButton
        PreprocessedEventRelatedDataButton  matlab.ui.control.StateButton
        SaveTypeDropDown        matlab.ui.control.DropDown
        SaveasDropDownLabel     matlab.ui.control.Label
        MultipleSelectionsPossibleLabel  matlab.ui.control.Label
        EventRelatedDataButton  matlab.ui.control.StateButton
        WhattosaveLabel         matlab.ui.control.Label
        SpikeIndiciesButton     matlab.ui.control.StateButton
        EventTimesButton        matlab.ui.control.StateButton
        PreprocessedDataButton  matlab.ui.control.StateButton
        RawDataButton           matlab.ui.control.StateButton
        RightPanel              matlab.ui.container.Panel
        TextArea                matlab.ui.control.TextArea
        InformationLabel        matlab.ui.control.Label
        CloseButton             matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Mainapp = []; % To save Main Window object
        Whattosave = []; % To capture, which datacomponents have to be saved
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            movegui(app.SaveDataWindowUIFigure, 'center');
                      
            app.Mainapp = Mainapp;
            
            app = Utility_Change_Light_Dark_Mode(app,"Save_Data_Window");
            % Initiate vectzor holding Info what should be saved. Each
            % indicie of Whattosave corresponds to a component of the
            % dataset. A 0 indicates, this component should not be saved.
            % Vector gets changed when user changes the selection. 
            % Components in the correct order are:
            % [Raw,Preprocessed,Events,Spikes,EventrelatedData] --> [1,1,1,0,0] saves raw data, preprocessed data and event time points
            
            app.OpenRawTraceinFieldTripDataBrowserCheckBox.Enable = "off";
            app.OpenRawTraceinFieldTripDataBrowserCheckBox.Visible = "off";

            app.OpenTrialDatainFieldTripDataBrowserCheckBox.Enable = "off";
            app.OpenTrialDatainFieldTripDataBrowserCheckBox.Visible = "off";


            value = app.SaveTypeDropDown.Value;
            SameText = ["In this window, NeuroMod’s internal data can be saved in different formats for future use in NeuroMod or other Matlab and Python toolboxes without having to extract the proprietary formats (and dataset components like spikes or events if applicable for the format) again (which may not even be supported by the respective toolbox you want to use).";"";"First select for which purpose you want to save, i.e. for further use in Neuromod, Neo or other toolboxes (like SpikeInterface and .nwb compatible toolboxes). Each of these options come with various formats in the ‘Saves as’ dropdown menu that can be loaded later.";"Dataset components to save can be added by clicking the corresponding button on the left (sticky selection). Event related data can only be saved for further use in NeuroMod.";"";"Standard folder to save data in is ‘Path_to_NeuroMod/Recording Data/Saved GUI Data’. This folder can of course be changed as you see fit, but it enables one click loads in the ‘Load Dataset’ menu later."];
            if strcmp(value,'SpikeInterface Compatible Binary File')
                texttoshow = "When saving for SpikeInterface, three files are saved in total. First the channel data is saved as a .bin file with data in double format with the name you selected when asked where to save. Additionally, two .json files are saved in the same folder with the first one containing meta data (called Filename_Meta_Data.json) and the second one containing your probe information (called Filename_probe.json). Use the 'Load_Saved_Data_For_SpikeInterface.py' function in the folder 'Path_to_NeuroMod/Modules/Misc/docs' to see how to load the data in SpikeInterface for further use and plot the probe design and channel data.";
            elseif strcmp(value,'.dat')
                texttoshow = "When saving for NeuroMod as .dat file, two files are saved in total. First the channel data is saved as a .dat with data in int16 format with the name you selected when asked where to save. Additionally, a .mat file contaning meta data is saved, caled 'NameofDatFile_Info.mat' in the same folder."; 
            elseif strcmp(value,'.mat')
                texttoshow = "When saving for NeuroMod as .mat file, just one file is saved containing the NeuroMod internal data structure as is."; 
            elseif strcmp(value,'Neo Compatible .dat File')
                texttoshow = "When saving for the python NEO libraray in a compatible .dat file that can be loaded into NEO, a .dat file containing channel data as well as a .json info file with relevant recording information are saved. NOTE: When saving spike data, it cannot be loaded back into Neuromod at the moment. Use the 'Load_Saved_Mat_For_Neo_Example.py' function in the folder 'Path_to_NeuroMod/Modules/Misc/docs' to see how to load the data into NEO for further in other python toolboxes as well as how to plot channel and event data (if present).";
            elseif strcmp(value,'NWB File (Neurodata Without Borders)')
                texttoshow = "When saving in the nwb format, two files are saved. The first one is the .nwb file itself containing the relevant data to load a .nwb in python using the pynwb library. The other file is a .mat file (newfilename_Info.mat) containing the Data.Info NeuroMod structure with all relevant meta data to load it back into NeuroMod (not necessary if reading the .nwb file itself). When you have internally thresholded spike data (not sorted), spikes can also be saved in the .nwb file itself (but not loaded back into NeuroMod at the moment). Use the 'Load_Saved_NWB_Example.py' function in the folder 'Path_to_NeuroMod/Modules/Misc/docs' to see how to load the data using pynwb and plot channel, event data and spike data (if present).";
            elseif strcmp(value,'FieldTrip Compatible .mat File')
                texttoshow = "This saves a single .mat file that can be loaded into the Matlab workspace and directly works with FieldTrip as is. For a reference how to use this file with FieldTrip in Matlab see the Use_Saved_FieldTrip_Compatible_Files_Example.m function in 'Path_to_NeuroMod/Modules/Misc/docs'.";
            end

            app.TextArea.Value = [SameText;"";texttoshow];

            app.Whattosave = zeros(1,6);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            %% Check if data is saved or just events, spikes or info. If no
            % data display warning
            
            %% Start Saving Data
            app.TextArea.Value = "Data is saved! Please wait until you get a message about the result!";
            pause(0.2);
            
            Error = 0;

            if strcmp(app.SaveTypeDropDown_2.Value,"NeuroMod")
                [filepath,Error] = Manage_Dataset_Module_SaveData(app.Mainapp.Data,app.SaveTypeDropDown.Value,app.Whattosave,app.Mainapp.executableFolder,"No");
            elseif strcmp(app.SaveTypeDropDown_2.Value,"Other")
                if strcmp(app.SaveTypeDropDown.Value,"NWB File (Neurodata Without Borders)")
                    
                    dashindex = find(app.Mainapp.Data.Info.Data_Path=='/');
                    if isempty(dashindex)
                        dashindex = find(app.Mainapp.Data.Info.Data_Path=='\');
                    end
                    OriginalfolderName = app.Mainapp.Data.Info.Data_Path(dashindex(end)+1:end); 
                    
                    if app.RawDataButton.Value == 1
                        [filepath,Error] = Manage_Dataset_SaveData_NWB(app.Mainapp.Data,app.Whattosave(3),app.Whattosave(4),"Raw Data",app.Mainapp.Data.Info.NativeSamplingRate,OriginalfolderName,"No",[]);
                    else
                        if isfield(app.Mainapp.Data.Info,'DownsampledSampleRate')
                            [filepath,Error] = Manage_Dataset_SaveData_NWB(app.Mainapp.Data,app.Whattosave(3),app.Whattosave(4),"Preprocessed Data",app.Mainapp.Data.Info.DownsampledSampleRate,OriginalfolderName,"No",[]);
                        else
                            [filepath,Error] = Manage_Dataset_SaveData_NWB(app.Mainapp.Data,app.Whattosave(3),app.Whattosave(4),"Preprocessed Data",app.Mainapp.Data.Info.NativeSamplingRate,OriginalfolderName,"No",[]);
                        end
                    end
                elseif strcmp(app.SaveTypeDropDown.Value,"SpikeInterface Compatible Binary File")
                    % managing potential downsampling in function!
                    if app.RawDataButton.Value == 1
                        [filepath] = Manage_Dataset_SaveData_SpikeInterfaceNumpy(app.Mainapp.Data,"Raw Data",app.Whattosave(3),"No",[]);
                    else
                        [filepath] = Manage_Dataset_SaveData_SpikeInterfaceNumpy(app.Mainapp.Data,"Preprocessed Data",app.Whattosave(3),"No",[]);
                    end
                elseif strcmp(app.SaveTypeDropDown.Value,"FieldTrip Compatible .mat File")

                    if app.PreprocessedDataButton.Value + app.RawDataButton.Value == 0
                        msgbox("Please select to either use raw or preprocessed data!");
                        return;
                    end
                    
                    if app.RawDataButton.Value == 1
                        [Error,filepath] = Manage_Dataset_SaveData_FieldTrip(app.Mainapp.Data,"Raw Data",app.Whattosave(3),1,app.OpenRawTraceinFieldTripDataBrowserCheckBox.Value,app.OpenTrialDatainFieldTripDataBrowserCheckBox.Value,"No",[],[],[]);
                    else
                        [Error,filepath] = Manage_Dataset_SaveData_FieldTrip(app.Mainapp.Data,"Preprocessed Data",app.Whattosave(3),1,app.OpenRawTraceinFieldTripDataBrowserCheckBox.Value,app.OpenTrialDatainFieldTripDataBrowserCheckBox.Value,"No",[],[],[]);
                    end
                end
                
                if strcmp(app.SaveTypeDropDown.Value,"Neo Compatible .dat File")
                    if app.Whattosave(1) == 1
                        [filepath,Error] = Manage_Dataset_SaveData_NeoDat(app.Mainapp.Data,app.Mainapp.Data.Info.NativeSamplingRate,"Raw Data",app.Whattosave(3),app.Whattosave(4),app.Mainapp.Data.Time,"No",[]);
                    else
                        if isfield(app.Mainapp.Data.Info,'DownsampledSampleRate')
                            [filepath,Error] = Manage_Dataset_SaveData_NeoDat(app.Mainapp.Data,app.Mainapp.Data.Info.DownsampledSampleRate,"Preprocessed Data",app.Whattosave(3),app.Whattosave(4),app.Mainapp.Data.TimeDownsampled,"No",[]);
                        else
                            [filepath,Error] = Manage_Dataset_SaveData_NeoDat(app.Mainapp.Data,app.Mainapp.Data.Info.NativeSamplingRate,"Preprocessed Data",app.Whattosave(3),app.Whattosave(4),app.Mainapp.Data.Time,"No",[]);
                        end
                    end
                end
            end

            %% Check if Saving was succesfull.
            if ~isempty(filepath) && Error == 0
                if strcmp(app.SaveTypeDropDown_2.Value,"NEO")

                    txttoshow{1} = convertStringsToChars(strcat("Data was succesfully saved to: ",filepath));
                    txttoshow{2} = '';
                    txttoshow{3} = convertStringsToChars("For an example how to load this data into the NEO python library and plot channel and event data, check out the example function 'Load_Saved_Mat_For_Neo_Example.py' that can be found in 'Path_to_NeuroMod/Modules/MISC/docs'.");

                    app.TextArea.Value = txttoshow;
                    pause(0.1);
                elseif strcmp(app.SaveTypeDropDown_2.Value,"Other")
                    txttoshow{1} = convertStringsToChars(strcat("Data was succesfully saved to: ",filepath));
                    txttoshow{2} = '';
                    if strcmp(app.SaveTypeDropDown.Value,"SpikeInterface Compatible Binary File")
                        txttoshow{3} = convertStringsToChars("For an example how to load the saved .bin and .json files into the SPikeINterface python library and plot the probe desing and channel data, check out the example function 'Load_Saved_Data_For_SpikeInterface.py' that can be found in 'Path_to_NeuroMod/Modules/MISC/docs'.");
                    else % NWB
                        txttoshow{3} = convertStringsToChars("For an example how to load the saved .nwb file using the pynwb python library and plot channel and event data, check out the example function 'Load_Saved_NWB_Example.py' that can be found in 'Path_to_NeuroMod/Modules/MISC/docs'.");
                    end
                    
                    app.TextArea.Value = txttoshow;
                    pause(0.1);
                else
                    txttoshow{1} = convertStringsToChars(strcat("Data was succesfully saved to: ",filepath));
                    txttoshow{2} = '';
                    txttoshow{3} = convertStringsToChars("Data can be loaded from that location later in NeuroMod without the need to extract data or dataset components again.");

                    app.TextArea.Value = txttoshow;
                    pause(0.1);
                end


            else
                app.TextArea.Value = convertStringsToChars("Saving Data failed. Check that you selected a proper save location and have permissions to create and write in files in that location!");
                pause(0.1);
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            try
                app.Mainapp.SaveDataWindow = [];
            end
            delete(app);
        end

        % Value changed function: RawDataButton
        function RawDataButtonValueChanged(app, event)
            % Check if Raw Data is part of the dataset. If yes, set
            % Whattosave(1,1) to 1, otherwise to 0
            if ~strcmp(app.SaveTypeDropDown_2.Value,"NeuroMod")
                if app.PreprocessedDataButton.Value == 1
                    app.PreprocessedDataButton.Value = 0;
                end
            end
            

            value = app.RawDataButton.Value;
            if value == 1
                if ~isfield(app.Mainapp.Data,"Raw")
                    f = msgbox("No raw data found!");
                    app.RawDataButton.Value = 0;
                else
                    app.Whattosave(1,1) = 1;
                end
            else
                app.Whattosave(1,1) = 0;
            end
         
        end

        % Value changed function: PreprocessedDataButton
        function PreprocessedDataButtonValueChanged(app, event)
            % Check if Preprocessed Data is part of the dataset. If yes, set
            % Whattosave(1,2) to 1, otherwise to 0

            if ~strcmp(app.SaveTypeDropDown_2.Value,"NeuroMod")
                if ~isfield(app.Mainapp.Data,"Preprocessed")
                    msgbox("No preprocessed data found!");
                    app.PreprocessedDataButton.Value = 0;
                    return;
                end

                if app.RawDataButton.Value == 1
                    app.RawDataButton.Value = 0;
                end
            end
            
      
            value = app.PreprocessedDataButton.Value;
            if value == 1
                if ~isfield(app.Mainapp.Data,"Preprocessed")
                    f = msgbox("No preprocessed data found!");
                    app.PreprocessedDataButton.Value = 0;
                else
                    app.Whattosave(1,2) = 1;
                end
            else
                app.Whattosave(1,2) = 0;
            end
           
        end

        % Value changed function: EventTimesButton
        function EventTimesButtonValueChanged(app, event)
            % Check if Event Data is part of the dataset. If yes, set
            % Whattosave(1,3) to 1, otherwise to 0
           
            value = app.EventTimesButton.Value;
            if value == 1
                if ~isfield(app.Mainapp.Data,"Events")
                    f = msgbox("No event data found!");
                    app.EventTimesButton.Value = 0;
                else
                    app.Whattosave(1,3) = 1;
                end
            else
                app.Whattosave(1,3) = 0;
                
                app.Whattosave(1,5) = 0;
                app.Whattosave(1,6) = 0;
                app.EventRelatedDataButton.Value = 0;
                app.PreprocessedEventRelatedDataButton.Value = 0;
            end
          
        end

        % Value changed function: SpikeIndiciesButton
        function SpikeIndiciesButtonValueChanged(app, event)
            % Check if Spike Data is part of the dataset. If yes, set
            % Whattosave(1,4) to 1, otherwise to 0
       
            value = app.SpikeIndiciesButton.Value;
            if value == 1
                if ~isfield(app.Mainapp.Data,"Spikes")
                    f = msgbox("No spike data found!");
                    app.SpikeIndiciesButton.Value = 0;
                else
                    app.Whattosave(1,4) = 1;
                end
            else
                app.Whattosave(1,4) = 0;
            end
          
        end

        % Value changed function: SelectAllButton
        function SelectAllButtonValueChanged(app, event)
            % Check existence of all data components and activate the ones
            % that are present 

            value = app.SelectAllButton.Value;
            if value == 1
                if isfield(app.Mainapp.Data,"Raw")
                    app.RawDataButton.Value = 1;
                    app.Whattosave(1,1) = 1;
                end
                if isfield(app.Mainapp.Data,"Preprocessed") && strcmp(app.SaveTypeDropDown_2.Value,"NeuroMod")
                    app.PreprocessedDataButton.Value = 1;
                    app.Whattosave(1,2) = 1;
                end
                if isfield(app.Mainapp.Data,"Events")
                    app.EventTimesButton.Value = 1;
                    app.Whattosave(1,3) = 1;
                end
                if isfield(app.Mainapp.Data,"Spikes")
                    app.SpikeIndiciesButton.Value = 1;
                    app.Whattosave(1,4) = 1;
                end
                if isfield(app.Mainapp.Data,"Events") && strcmp(app.SaveTypeDropDown_2.Value,"NeuroMod")
                    app.EventRelatedDataButton.Value = 1;
                    app.Whattosave(1,5) = 1;
                end
                if isfield(app.Mainapp.Data.Info,"EventRelatedPreprocessing") && strcmp(app.SaveTypeDropDown_2.Value,"NeuroMod")
                    app.PreprocessedEventRelatedDataButton.Value = 1;
                    app.Whattosave(1,6) = 1;
                end

            else
                app.RawDataButton.Value = 0;
                app.PreprocessedDataButton.Value = 0;
                app.EventTimesButton.Value = 0;
                app.SpikeIndiciesButton.Value = 0;
                app.EventRelatedDataButton.Value = 0;
                app.Whattosave(1,1:6) = 0;
            end
           

            app.SelectAllButton.Value = 0;

        end

        % Value changed function: EventRelatedDataButton
        function EventRelatedDataButtonValueChanged(app, event)
            % Check if Event Related Data is part of the dataset. If yes, set
            % Whattosave(1,5) to 1, otherwise to 0
            if strcmp(app.SaveTypeDropDown_2.Value,"NeuroMod")
                value = app.EventRelatedDataButton.Value;
                if value == 1
                    if ~isfield(app.Mainapp.Data,"Events")
                        msgbox("No event trigger data found!");
                        app.EventRelatedDataButton.Value = 0;
                    else
                        if app.EventTimesButton.Value == 0
                            app.EventTimesButton.Value = 1;
                            app.Whattosave(1,3) = 1;
                            disp("Automatically enabled to save event trigger data since its necessray for the GUI to work with event related data!")
                        end
                        app.Whattosave(1,5) = 1;
                    end
                else
                    app.Whattosave(1,5) = 0;
    
                    if app.PreprocessedEventRelatedDataButton.Value == 1
                        app.PreprocessedEventRelatedDataButton.Value = 0;
                        app.Whattosave(1,6) = 0;
                        disp("Automatically disabled to save preprocessed event data since raw event related data is necessray for it to work properly in the GUI!")
                    end
                end
            end
        end

        % Value changed function: PreprocessedEventRelatedDataButton
        function PreprocessedEventRelatedDataButtonValueChanged(app, event)
            value = app.PreprocessedEventRelatedDataButton.Value;
            % Check if Event Data is part of the dataset. If yes, set
            % Whattosave(1,6) to 1, otherwise to 0
            if strcmp(app.SaveTypeDropDown_2.Value,"NeuroMod")
                if value == 1
                    if ~isfield(app.Mainapp.Data.Info,"EventRelatedPreprocessing")
                        f = msgbox("No preprocessing information for event related data found!");
                        app.PreprocessedEventRelatedDataButton.Value = 0;
                    else
                        if app.EventTimesButton.Value == 0
                            app.EventTimesButton.Value = 1;
                            app.Whattosave(1,3) = 1;
                            disp("Automatically enabled to save event trigger data since its necessray for the GUI to work with preprocesed event related data!")
                        end
                        if app.EventRelatedDataButton.Value == 0
                            app.EventRelatedDataButton.Value = 1;
                            app.Whattosave(1,5) = 1;
                            disp("Automatically enabled to save raw event event related data since its necessray for preprocessed event related data to work in th GUI!")
                        end
                        app.Whattosave(1,6) = 1;
                    end
                else
                    app.Whattosave(1,6) = 0;
                end
            end
        end

        % Close request function: SaveDataWindowUIFigure
        function SaveDataWindowUIFigureCloseRequest(app, event)
            try
                app.Mainapp.SaveDataWindow = [];
            end
            delete(app);
        end

        % Value changed function: SaveTypeDropDown_2
        function SaveTypeDropDown_2ValueChanged(app, event)
            app.Whattosave = zeros(1,6);
            app.RawDataButton.Value = 0;
            app.PreprocessedDataButton.Value = 0;
            app.SpikeIndiciesButton.Value = 0;

            app.EventRelatedDataButton.Value = 0;
            app.EventTimesButton.Value = 0;
            app.PreprocessedEventRelatedDataButton.Value = 0;

            value = app.SaveTypeDropDown_2.Value;
            
            if strcmp(value,"NeuroMod")
                app.SaveTypeDropDown.Items = {};
                app.SaveTypeDropDown.Items{1} = '.dat';
                app.SaveTypeDropDown.Items{2} = '.mat';

                app.EventRelatedDataButton.Enable = "on";
                app.EventTimesButton.Enable = "on";

                app.SpikeIndiciesButton.Enable = "on";
                app.SpikeIndiciesButton.Visible = "on";

                app.PreprocessedEventRelatedDataButton.Enable = "on";
                app.PreprocessedEventRelatedDataButton.Visible = "on";

                app.OpenRawTraceinFieldTripDataBrowserCheckBox.Enable = "off";
                app.OpenRawTraceinFieldTripDataBrowserCheckBox.Visible = "off";
    
                app.OpenTrialDatainFieldTripDataBrowserCheckBox.Enable = "off";
                app.OpenTrialDatainFieldTripDataBrowserCheckBox.Visible = "off";

            elseif strcmp(value,"Other")
                app.SaveTypeDropDown.Items = {};
                app.SaveTypeDropDown.Items{1} = 'NWB File (Neurodata Without Borders)';
                app.SaveTypeDropDown.Items{2} = 'SpikeInterface Compatible Binary File';
                app.SaveTypeDropDown.Items{3} = 'Neo Compatible .dat File';
                app.SaveTypeDropDown.Items{4} = 'FieldTrip Compatible .mat File';

                app.EventRelatedDataButton.Enable = "off";
                app.EventTimesButton.Enable = "on";
                app.PreprocessedEventRelatedDataButton.Enable = "off";

                app.PreprocessedEventRelatedDataButton.Enable = "off";
                app.PreprocessedEventRelatedDataButton.Visible = "on";

                app.SpikeIndiciesButton.Enable = "on";
                app.SpikeIndiciesButton.Visible = "on";

                app.OpenRawTraceinFieldTripDataBrowserCheckBox.Enable = "off";
                app.OpenRawTraceinFieldTripDataBrowserCheckBox.Visible = "off";
    
                app.OpenTrialDatainFieldTripDataBrowserCheckBox.Enable = "off";
                app.OpenTrialDatainFieldTripDataBrowserCheckBox.Visible = "off";
            end

            value = app.SaveTypeDropDown.Value;
            SameText = ["In this window, NeuroMod’s internal data can be saved in different formats for future use in NeuroMod or other Matlab and Python toolboxes without having to extract the proprietary formats (and dataset components like spikes or events if applicable for the format) again (which may not even be supported by the respective toolbox you want to use).";"";"First select for which purpose you want to save, i.e. for further use in Neuromod, Neo or other toolboxes (like SpikeInterface and .nwb compatible toolboxes). Each of these options come with various formats in the ‘Saves as’ dropdown menu that can be loaded later.";"Dataset components to save can be added by clicking the corresponding button on the left (sticky selection). Event related data can only be saved for further use in NeuroMod.";"";"Standard folder to save data in is ‘Path_to_NeuroMod/Recording Data/Saved GUI Data’. This folder can of course be changed as you see fit, but it enables one click loads in the ‘Load Dataset’ menu later."];
            if strcmp(value,'SpikeInterface Compatible Binary File')
                texttoshow = "When saving for SpikeInterface, three files are saved in total. First the channel data is saved as a .bin file with data in double format with the name you selected when asked where to save. Additionally, two .json files are saved in the same folder with the first one containing meta data (called Filename_Meta_Data.json) and the second one containing your probe information (called Filename_probe.json). Use the 'Load_Saved_Data_For_SpikeInterface.py' function in the folder 'Path_to_NeuroMod/Modules/Misc/docs' to see how to load the data in SpikeInterface for further use and plot the probe design and channel data.";
            elseif strcmp(value,'.dat')
                texttoshow = "When saving for NeuroMod as .dat file, two files are saved in total. First the channel data is saved as a .dat with data in int16 format with the name you selected when asked where to save. Additionally, a .mat file contaning meta data is saved, caled 'NameofDatFile_Info.mat' in the same folder."; 
            elseif strcmp(value,'.mat')
                texttoshow = "When saving for NeuroMod as .mat file, just one file is saved containing the NeuroMod internal data structure as is."; 
            elseif strcmp(value,'Neo Compatible .dat File')
                texttoshow = "When saving for the python NEO libraray in a compatible .dat file that can be loaded into NEO, a .dat file containing channel data as well as a .json info file with relevant recording information are saved. NOTE: When saving spike data, it cannot be loaded back into Neuromod at the moment. Use the 'Load_Saved_Mat_For_Neo_Example.py' function in the folder 'Path_to_NeuroMod/Modules/Misc/docs' to see how to load the data into NEO for further in other python toolboxes as well as how to plot channel and event data (if present).";
            elseif strcmp(value,'NWB File (Neurodata Without Borders)')
                texttoshow = "When saving in the nwb format, two files are saved. The first one is the .nwb file itself containing the relevant data to load a .nwb in python using the pynwb library. The other file is a .mat file (newfilename_Info.mat) containing the Data.Info NeuroMod structure with all relevant meta data to load it back into NeuroMod (not necessary if reading the .nwb file itself). When you have internally thresholded spike data (not sorted), spikes can also be saved in the .nwb file itself (but not loaded back into NeuroMod at the moment). Use the 'Load_Saved_NWB_Example.py' function in the folder 'Path_to_NeuroMod/Modules/Misc/docs' to see how to load the data using pynwb and plot channel, event data and spike data (if present).";
            elseif strcmp(value,'FieldTrip Compatible .mat File')
                texttoshow = "This saves a single .mat file that can be loaded into the Matlab workspace and directly works with FieldTrip as is. For a reference how to use this file with FieldTrip in Matlab see the Use_Saved_FieldTrip_Compatible_Files_Example.m function in 'Path_to_NeuroMod/Modules/Misc/docs'.";
            end

            if strcmp(value,'FieldTrip Compatible .mat File')
                app.SpikeIndiciesButton.Enable = "off";
                app.SpikeIndiciesButton.Visible = "off";

                app.PreprocessedEventRelatedDataButton.Enable = "off";
                app.PreprocessedEventRelatedDataButton.Visible = "off";

                app.OpenRawTraceinFieldTripDataBrowserCheckBox.Enable = "on";
                app.OpenRawTraceinFieldTripDataBrowserCheckBox.Visible = "on";
    
                app.OpenTrialDatainFieldTripDataBrowserCheckBox.Enable = "on";
                app.OpenTrialDatainFieldTripDataBrowserCheckBox.Visible = "on";
            end

            app.TextArea.Value = [SameText;"";texttoshow];
        end

        % Value changed function: SaveTypeDropDown
        function SaveTypeDropDownValueChanged(app, event)
            value = app.SaveTypeDropDown.Value;
            SameText = ["In this window, NeuroMod’s internal data can be saved in different formats for future use in NeuroMod or other Matlab and Python toolboxes without having to extract the proprietary formats (and dataset components like spikes or events if applicable for the format) again (which may not even be supported by the respective toolbox you want to use).";"";"First select for which purpose you want to save, i.e. for further use in Neuromod, Neo or other toolboxes (like SpikeInterface and .nwb compatible toolboxes). Each of these options come with various formats in the ‘Saves as’ dropdown menu that can be loaded later.";"Dataset components to save can be added by clicking the corresponding button on the left (sticky selection). Event related data can only be saved for further use in NeuroMod.";"";"Standard folder to save data in is ‘Path_to_NeuroMod/Recording Data/Saved GUI Data’. This folder can of course be changed as you see fit, but it enables one click loads in the ‘Load Dataset’ menu later."];
            if strcmp(value,'SpikeInterface Compatible Binary File')
                texttoshow = "When saving for SpikeInterface, three files are saved in total. First the channel data is saved as a .bin file with data in double format with the name you selected when asked where to save. Additionally, two .json files are saved in the same folder with the first one containing meta data (called Filename_Meta_Data.json) and the second one containing your probe information (called Filename_probe.json). Use the 'Load_Saved_Data_For_SpikeInterface.py' function in the folder 'Path_to_NeuroMod/Modules/Misc/docs' to see how to load the data in SpikeInterface for further use and plot the probe design and channel data.";
            elseif strcmp(value,'.dat')
                texttoshow = "When saving for NeuroMod as .dat file, two files are saved in total. First the channel data is saved as a .dat with data in int16 format with the name you selected when asked where to save. Additionally, a .mat file contaning meta data is saved, caled 'NameofDatFile_Info.mat' in the same folder."; 
            elseif strcmp(value,'.mat')
                texttoshow = "When saving for NeuroMod as .mat file, just one file is saved containing the NeuroMod internal data structure as is."; 
            elseif strcmp(value,'Neo Compatible .dat File')
                texttoshow = "When saving for the python NEO libraray in a compatible .dat file that can be loaded into NEO, a .dat file containing channel data as well as a .json info file with relevant recording information are saved. NOTE: When saving spike data, it cannot be loaded back into Neuromod at the moment. Use the 'Load_Saved_Mat_For_Neo_Example.py' function in the folder 'Path_to_NeuroMod/Modules/Misc/docs' to see how to load the data into NEO for further in other python toolboxes as well as how to plot channel and event data (if present).";
            elseif strcmp(value,'NWB File (Neurodata Without Borders)')
                texttoshow = "When saving in the nwb format, two files are saved. The first one is the .nwb file itself containing the relevant data to load a .nwb in python using the pynwb library. The other file is a .mat file (newfilename_Info.mat) containing the Data.Info NeuroMod structure with all relevant meta data to load it back into NeuroMod (not necessary if reading the .nwb file itself). When you have internally thresholded spike data (not sorted), spikes can also be saved in the .nwb file itself (but not loaded back into NeuroMod at the moment). Use the 'Load_Saved_NWB_Example.py' function in the folder 'Path_to_NeuroMod/Modules/Misc/docs' to see how to load the data using pynwb and plot channel, event data and spike data (if present).";
            elseif strcmp(value,'FieldTrip Compatible .mat File')
                texttoshow = "This saves a single .mat file that can be loaded into the Matlab workspace and directly works with FieldTrip as is. For a reference how to use this file with FieldTrip in Matlab see the Use_Saved_FieldTrip_Compatible_Files_Example.m function in 'Path_to_NeuroMod/Modules/Misc/docs'.";
            end
            
            if strcmp(value,'FieldTrip Compatible .mat File')
                app.SpikeIndiciesButton.Enable = "off";
                app.SpikeIndiciesButton.Visible = "off";
                app.SpikeIndiciesButton.Value = 0;

                app.PreprocessedEventRelatedDataButton.Enable = "off";
                app.PreprocessedEventRelatedDataButton.Visible = "off";

                app.OpenRawTraceinFieldTripDataBrowserCheckBox.Enable = "on";
                app.OpenRawTraceinFieldTripDataBrowserCheckBox.Visible = "on";
    
                app.OpenTrialDatainFieldTripDataBrowserCheckBox.Enable = "on";
                app.OpenTrialDatainFieldTripDataBrowserCheckBox.Visible = "on";

            elseif strcmp(value,'.dat')
                app.SpikeIndiciesButton.Enable = "on";
                app.SpikeIndiciesButton.Visible = "on";

                app.PreprocessedEventRelatedDataButton.Enable = "on";
                app.PreprocessedEventRelatedDataButton.Visible = "on";

                app.OpenRawTraceinFieldTripDataBrowserCheckBox.Enable = "off";
                app.OpenRawTraceinFieldTripDataBrowserCheckBox.Visible = "off";
    
                app.OpenTrialDatainFieldTripDataBrowserCheckBox.Enable = "off";
                app.OpenTrialDatainFieldTripDataBrowserCheckBox.Visible = "off";

            elseif strcmp(value,'NWB File (Neurodata Without Borders)')
                if strcmp(app.Mainapp.Data.Info.SpikeType,"Internal")
                    app.SpikeIndiciesButton.Enable = "on";
                    app.SpikeIndiciesButton.Visible = "on";
                else
                    app.SpikeIndiciesButton.Value = 0;
                    app.SpikeIndiciesButton.Enable = "off";
                    app.SpikeIndiciesButton.Visible = "on";
                end

                app.PreprocessedEventRelatedDataButton.Enable = "off";
                app.PreprocessedEventRelatedDataButton.Visible = "on";

                app.OpenRawTraceinFieldTripDataBrowserCheckBox.Enable = "off";
                app.OpenRawTraceinFieldTripDataBrowserCheckBox.Visible = "off";
    
                app.OpenTrialDatainFieldTripDataBrowserCheckBox.Enable = "off";
                app.OpenTrialDatainFieldTripDataBrowserCheckBox.Visible = "off";

            else
                app.SpikeIndiciesButton.Enable = "on";
                app.SpikeIndiciesButton.Visible = "on";

                app.PreprocessedEventRelatedDataButton.Enable = "off";
                app.PreprocessedEventRelatedDataButton.Visible = "on";

                app.OpenRawTraceinFieldTripDataBrowserCheckBox.Enable = "off";
                app.OpenRawTraceinFieldTripDataBrowserCheckBox.Visible = "off";
    
                app.OpenTrialDatainFieldTripDataBrowserCheckBox.Enable = "off";
                app.OpenTrialDatainFieldTripDataBrowserCheckBox.Visible = "off";

            end

            if strcmp(value,'SpikeInterface Compatible Binary File')
                app.SpikeIndiciesButton.Enable = "off";
                app.SpikeIndiciesButton.Visible = "on";
                app.SpikeIndiciesButton.Value = 0;
            end

            app.TextArea.Value = [SameText;"";texttoshow];
        end

        % Drop down opening function: SaveTypeDropDown_2
        function SaveTypeDropDown_2Opening(app, event)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SaveDataWindowUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {468, 468};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {291, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SaveDataWindowUIFigure = uifigure('Visible', 'off');
            app.SaveDataWindowUIFigure.Name = 'Save Data Window';
            app.SaveDataWindowUIFigure.Icon = 'Logo.png';
            app.SaveDataWindowUIFigure.CloseRequestFcn = createCallbackFcn(app, @SaveDataWindowUIFigureCloseRequest, true);
            app.SaveDataWindowUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.SaveDataWindowUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SaveOptionsPanel = uipanel(app.LeftPanel);
            app.SaveOptionsPanel.Title = 'Save Options';
            app.RawDataButton = uibutton(app.SaveOptionsPanel, 'state');
            app.RawDataButton.ValueChangedFcn = createCallbackFcn(app, @RawDataButtonValueChanged, true);
            app.RawDataButton.Text = 'Raw Data';
            app.PreprocessedDataButton = uibutton(app.SaveOptionsPanel, 'state');
            app.PreprocessedDataButton.ValueChangedFcn = createCallbackFcn(app, @PreprocessedDataButtonValueChanged, true);
            app.PreprocessedDataButton.Text = 'Preprocessed Data';
            app.EventTimesButton = uibutton(app.SaveOptionsPanel, 'state');
            app.EventTimesButton.ValueChangedFcn = createCallbackFcn(app, @EventTimesButtonValueChanged, true);
            app.EventTimesButton.Text = 'Events';
            app.SpikeIndiciesButton = uibutton(app.SaveOptionsPanel, 'state');
            app.SpikeIndiciesButton.ValueChangedFcn = createCallbackFcn(app, @SpikeIndiciesButtonValueChanged, true);
            app.SpikeIndiciesButton.Text = 'Spike Indicies';
            app.WhattosaveLabel = uilabel(app.SaveOptionsPanel);
            app.WhattosaveLabel.Text = 'What to save';
            app.EventRelatedDataButton = uibutton(app.SaveOptionsPanel, 'state');
            app.EventRelatedDataButton.ValueChangedFcn = createCallbackFcn(app, @EventRelatedDataButtonValueChanged, true);
            app.EventRelatedDataButton.Text = 'Event Related Data';
            app.MultipleSelectionsPossibleLabel = uilabel(app.SaveOptionsPanel);
            app.MultipleSelectionsPossibleLabel.Text = 'Multiple Selections Possible';
            app.SaveasDropDownLabel = uilabel(app.SaveOptionsPanel);
            app.SaveasDropDownLabel.Text = 'Save as';
            app.SaveTypeDropDown = uidropdown(app.SaveOptionsPanel);
            app.SaveTypeDropDown.Items = {'.mat', '.dat'};
            app.SaveTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SaveTypeDropDownValueChanged, true);
            app.SaveTypeDropDown.Value = '.dat';
            app.PreprocessedEventRelatedDataButton = uibutton(app.SaveOptionsPanel, 'state');
            app.PreprocessedEventRelatedDataButton.ValueChangedFcn = createCallbackFcn(app, @PreprocessedEventRelatedDataButtonValueChanged, true);
            app.PreprocessedEventRelatedDataButton.Text = {'Preprocessed Event'; 'Related Data'};
            app.SelectAllButton = uibutton(app.SaveOptionsPanel, 'state');
            app.SelectAllButton.ValueChangedFcn = createCallbackFcn(app, @SelectAllButtonValueChanged, true);
            app.SelectAllButton.Text = 'Select All';
            app.SaveButton = uibutton(app.SaveOptionsPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SaveOptionsLabel = uilabel(app.SaveOptionsPanel);
            app.SaveOptionsLabel.Text = 'Save Options';
            app.SaveforLabel = uilabel(app.SaveOptionsPanel);
            app.SaveforLabel.Text = 'Save for';
            app.SaveTypeDropDown_2 = uidropdown(app.SaveOptionsPanel);
            app.SaveTypeDropDown_2.Items = {'NeuroMod', 'Other'};
            app.SaveTypeDropDown_2.DropDownOpeningFcn = createCallbackFcn(app, @SaveTypeDropDown_2Opening, true);
            app.SaveTypeDropDown_2.ValueChangedFcn = createCallbackFcn(app, @SaveTypeDropDown_2ValueChanged, true);
            app.SaveTypeDropDown_2.Value = 'NeuroMod';
            app.OpenRawTraceinFieldTripDataBrowserCheckBox = uicheckbox(app.SaveOptionsPanel);
            app.OpenRawTraceinFieldTripDataBrowserCheckBox.Text = {'   Open Raw Trace '; '   in FieldTrip'; '   Data Browser'};
            app.OpenTrialDatainFieldTripDataBrowserCheckBox = uicheckbox(app.SaveOptionsPanel);
            app.OpenTrialDatainFieldTripDataBrowserCheckBox.Text = {'   Open Trial Data '; '   in FieldTrip'; '   Data Browser'};
            app.RightPanel = uipanel(app.GridLayout);
            app.CloseButton = uibutton(app.RightPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.InformationLabel = uilabel(app.RightPanel);
            app.InformationLabel.Text = 'Information';
            app.TextArea = uitextarea(app.RightPanel);
            app.TextArea.Editable = 'off';
            % Show the figure after all components are created
            app.SaveDataWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Save_Data_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SaveDataWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.SaveDataWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SaveDataWindowUIFigure)
        end
    end
end