classdef CSD_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CSDWindowUIFigure               matlab.ui.Figure
        SnapshotMenu                    matlab.ui.container.Menu
        SaveSnapshotofWindowMenu        matlab.ui.container.Menu
        PNGMenu                         matlab.ui.container.Menu
        JPGMenu                         matlab.ui.container.Menu
        SVGMenu                         matlab.ui.container.Menu
        EPSMenu                         matlab.ui.container.Menu
        PlotMenu                        matlab.ui.container.Menu
        Show2DimensionalPlotsMenu       matlab.ui.container.Menu
        Show3DimensionalPlotsMenu       matlab.ui.container.Menu
        ChangePlotPropertiesMenu        matlab.ui.container.Menu
        CSDPlotMenu                     matlab.ui.container.Menu
        FontSizeMenu                    matlab.ui.container.Menu
        BackgorundColorMenu             matlab.ui.container.Menu
        XLabelMenu                      matlab.ui.container.Menu
        YLabelMenu                      matlab.ui.container.Menu
        CLabelMenu                      matlab.ui.container.Menu
        RESETMenu                       matlab.ui.container.Menu
        SaveCustomeAppearanceMenu       matlab.ui.container.Menu
        DeleteCustomeAppearanceMenu     matlab.ui.container.Menu
        ExportDataMenu                  matlab.ui.container.Menu
        matMenu                         matlab.ui.container.Menu
        txtMenu                         matlab.ui.container.Menu
        xlsxMenu                        matlab.ui.container.Menu
        TimeWindowfromtoinsEditField    matlab.ui.control.EditField
        AnalysisTimeRangefromtoinsEditFieldLabel  matlab.ui.control.Label
        Button                          matlab.ui.control.Button
        LeftPanel                       matlab.ui.container.Panel
        CloseButton                     matlab.ui.control.Button
        CurrentSourceDensityOptionsPanel  matlab.ui.container.Panel
        CoupleTimetoMainWindowCheckBox  matlab.ui.control.CheckBox
        LockCLimCheckBox                matlab.ui.control.CheckBox
        CurrentSourceDensityOptionsLabel  matlab.ui.control.Label
        DataTypeDropDownLabel           matlab.ui.control.Label
        DataTypeDropDown                matlab.ui.control.DropDown
        HammWindowEditField             matlab.ui.control.EditField
        HammWindowEditFieldLabel        matlab.ui.control.Label
        ChannelSelectionfromtoEditField  matlab.ui.control.EditField
        ChannelSelectionfromtoEditFieldLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Mainapp = []; % Description
        ExistflagCSD =  [];
        CSDClim = [];
        TwoORThreeD = "TwoD"; % standard 2d plots when possible
        Startup = 1;
        ChangeMainPlot = 1;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            movegui(app.CSDWindowUIFigure, 'north');

            app.Mainapp = Mainapp;
            app.Mainapp.CSDApp = app;

            app = Utility_Change_Light_Dark_Mode(app,"LiveCSDWindow");
            
            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.LiveCSDWindow.FontSize;
            app.UIAxes.Color = app.Mainapp.PlotAppearance.LiveCSDWindow.BackgroundColor;

            if isfield(app.Mainapp.Data,'Preprocessed')
                app.DataTypeDropDown.Items{1} = 'Raw Data';
                app.DataTypeDropDown.Items{2} = 'Preprocessed Data';
            else
                app.DataTypeDropDown.Items{1} = 'Raw Data';
            end

            if strcmp(app.Mainapp.PlotAppearance.MainWindow.Data.Plottype, "Surf") || strcmp(app.Mainapp.PlotAppearance.MainWindow.Data.Plottype,"Mesh")
                app.CoupleTimetoMainWindowCheckBox.Value = 0;
                disp("Disabling couple to main window since just one data point is plotted there in grid activity view mode!");
                app.TimeWindowfromtoinsEditField.Enable = "on";
                if isempty(app.TimeWindowfromtoinsEditField.Value)
                    
                    TimeRangePlot = 1.5;
                    if contains(app.DataTypeDropDown.Value,"Preprocessed Data")
                        if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                            StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                        else
                            StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                        end
                    else
                        StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                    end
                    
                    if StartTime<app.Mainapp.Data.Time(end) || StartTime+TimeRangePlot<app.Mainapp.Data.Time(end)
                        app.TimeWindowfromtoinsEditField.Value = strcat(num2str(StartTime),",",num2str(StartTime+TimeRangePlot));
                    else
                        StartTime = app.Mainapp.Data.Time(end)-1;
                        TimeRangePlot = 0.9;
                        app.TimeWindowfromtoinsEditField.Value = strcat(num2str(StartTime),",",num2str(StartTime+TimeRangePlot));
                    end
                end
            end
            if strcmp(app.Mainapp.PlotAppearance.MainWindow.Data.Plottype, "AxonViewer")
                app.CoupleTimetoMainWindowCheckBox.Value = 0;
                disp("Disabling couple to main window since just one data point is plotted there in axon viewer view mode!")
                app.TimeWindowfromtoinsEditField.Enable = "on";
                if isempty(app.TimeWindowfromtoinsEditField.Value)

                    TimeRangePlot = 1.5;
                    if strcmp(app.DataTypeDropDown.Value,"Preprocessed Data")
                        if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                            StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                        else
                            StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                        end
                    else
                        StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                    end

                    if StartTime<app.Mainapp.Data.Time(end) || StartTime+TimeRangePlot<app.Mainapp.Data.Time(end)
                        app.TimeWindowfromtoinsEditField.Value = strcat(num2str(StartTime),",",num2str(StartTime+TimeRangePlot));
                    else
                        StartTime = app.Mainapp.Data.Time(end)-1;
                        TimeRangePlot = 0.9;
                        app.TimeWindowfromtoinsEditField.Value = strcat(num2str(StartTime),",",num2str(StartTime+TimeRangePlot));
                    end
                end
            end
            
            app.UIAxes.YDir = 'reverse';
            
            app.ChannelSelectionfromtoEditField.Value = strcat(num2str(1),",",num2str(size(app.Mainapp.Data.Raw,1)));
           
            app.ExistflagCSD = 1;
        
            app.Mainapp.CSDApp = app;
            app.ChangeMainPlot = 0;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1;

            app.Startup = 0;
        end

        % Close request function: CSDWindowUIFigure
        function CSDWindowUIFigureCloseRequest(app, event)
            
            try
                app.Mainapp.CSDApp = [];
                app.ChangeMainPlot = 1;
                
                if ~isempty(app.Mainapp.ProbeViewWindowHandle) % delete option from probe view 
                    for i = 1:length(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                        if strcmp(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Main Plot Current Source Density')
                            app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items(i) = [];
                            break;
                        end
                    end
                else % if no probe view available
     
                end
            catch

            end

            delete(app)
        end

        % Callback function
        function ChannelSpacingumEditFieldValueChanged(app, event)
           
        end

        % Callback function
        function HammWindowEditFieldValueChanged(app, event)
                        
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            
            try
                app.ChangeMainPlot = 1;
                app.Mainapp.TwoORThreeD = "TwoD";  
                app.Mainapp.CSDApp = [];
                
                if ~isempty(app.Mainapp.ProbeViewWindowHandle) % delete option from probe view 
                    for i = 1:length(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                        if strcmp(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Main Plot Current Source Density')
                            app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items(i) = [];
                            break;
                        end
                    end
                else % if no probe view available
     
                end
            end
            app.ExistflagCSD = 0;
            delete(app)
        end

        % Callback function
        function ChannelSelectionfromtoEditFieldValueChanged(app, event)
            
        end

        % Value changed function: HammWindowEditField
        function HammWindowEditFieldValueChanged2(app, event)
            [app.HammWindowEditField.Value] = Utility_SimpleCheckInputs(app.HammWindowEditField.Value,"One",'5',0,0);
            
            if mod(str2double(app.HammWindowEditField.Value),2) == 0 %even
                msgbox("Warning: Only uneven numbers allowed!")
                app.HammWindowEditField.Value = event.PreviousValue;
            end
            
            app.ChangeMainPlot = 0;
            app.Mainapp.CSDApp = app;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1;
        end

        % Value changed function: ChannelSelectionfromtoEditField
        function ChannelSelectionfromtoEditFieldValueChanged2(app, event)
            
        end

        % Value changed function: LockCLimCheckBox
        function LockCLimCheckBoxValueChanged(app, event)
                        
        end

        % Menu selected function: PNGMenu
        function PNGMenuSelected(app, event)
            [file, path] = uiputfile('*.png', 'Save as');

            exportapp(app.CSDWindowUIFigure,fullfile(path,file)) 
        end

        % Menu selected function: JPGMenu
        function JPGMenuSelected(app, event)
            [file, path] = uiputfile('*.jpg', 'Save as');

            exportapp(app.CSDWindowUIFigure,fullfile(path,file)) 
        end

        % Menu selected function: Show2DimensionalPlotsMenu
        function Show2DimensionalPlotsMenuSelected(app, event)
            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";

            app.UIAxes.YDir = 'reverse';
            
            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.LiveCSDWindow.FontSize;
            app.UIAxes.Color = app.Mainapp.PlotAppearance.LiveCSDWindow.BackgroundColor;

            app.TwoORThreeD = "TwoD";

            app.Mainapp.CSDApp = app;
            app.ChangeMainPlot = 0;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1;
        end

        % Menu selected function: matMenu
        function matMenuSelected(app, event)
            TimeRangePlot = str2double(app.Mainapp.TimeRangeViewBox.Value(1:end-1));
            if strcmp(app.DataTypeDropDown.Value,"Preprocessed Data")
                if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                else
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                end
            else
                StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
            end

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Live_CSD_Main_Window",0);
        end

        % Menu selected function: txtMenu
        function txtMenuSelected(app, event)
            TimeRangePlot = str2double(app.Mainapp.TimeRangeViewBox.Value(1:end-1));
            if strcmp(app.DataTypeDropDown.Value,"Preprocessed Data")
                if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                else
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                end
            else
                StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
            end

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Live_CSD_Main_Window",0);
        end

        % Menu selected function: Show3DimensionalPlotsMenu
        function Show3DimensionalPlotsMenuSelected(app, event)
            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";
            
            app.UIAxes.YDir = 'reverse';

            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.LiveCSDWindow.FontSize;
            app.UIAxes.Color = app.Mainapp.PlotAppearance.LiveCSDWindow.BackgroundColor;

            app.TwoORThreeD = "ThreeD";

            app.Mainapp.CSDApp = app;
            app.ChangeMainPlot = 0;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1;
        end

        % Menu selected function: FontSizeMenu
        function FontSizeMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'fontsize',app.Mainapp,"LiveCSDWindowDataFontSize");
            waitfor(h);

            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.LiveCSDWindow.FontSize;

        end

        % Menu selected function: BackgorundColorMenu
        function BackgorundColorMenuSelected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.Mainapp.PlotAppearance.LiveCSDWindow.BackgroundColor = selectedColor;
                app.UIAxes.Color = app.Mainapp.PlotAppearance.LiveCSDWindow.BackgroundColor;
            end
        end

        % Menu selected function: XLabelMenu
        function XLabelMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'xlabel',app.Mainapp,"LiveCSDXlabel");
            waitfor(h);

            app.Mainapp.CSDApp = app;
            app.ChangeMainPlot = 0;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1;
        end

        % Menu selected function: YLabelMenu
        function YLabelMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'ylabel',app.Mainapp,"LiveCSDYlabel");
            waitfor(h);

            app.Mainapp.CSDApp = app;
            app.ChangeMainPlot = 0;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1;
        end

        % Menu selected function: CLabelMenu
        function CLabelMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'clabel',app.Mainapp,"LiveCSDClabel");
            waitfor(h);

            app.Mainapp.CSDApp = app;
            app.ChangeMainPlot = 0;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1;
        end

        % Menu selected function: RESETMenu
        function RESETMenuSelected(app, event)
            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";

            app.UIAxes.YDir = 'reverse';

            [app.Mainapp.PlotAppearance] = Organize_Set_Standard_PlotAppearance("LiveCSDPlot",app.Mainapp.PlotAppearance);

            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.LiveCSDWindow.FontSize;
            app.UIAxes.Color = app.Mainapp.PlotAppearance.LiveCSDWindow.BackgroundColor;
            
            app.Mainapp.CSDApp = app;
            app.ChangeMainPlot = 0;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1;
        end

        % Menu selected function: SaveCustomeAppearanceMenu
        function SaveCustomeAppearanceMenuSelected(app, event)
            app.Mainapp.PlotAppearance = Utility_Save_Load_Delete_Plot_Appearance(app.Mainapp.PlotAppearance,app.Mainapp.executableFolder,"Save");
        end

        % Menu selected function: DeleteCustomeAppearanceMenu
        function DeleteCustomeAppearanceMenuSelected(app, event)
            app.Mainapp.PlotAppearance = Utility_Save_Load_Delete_Plot_Appearance(app.Mainapp.PlotAppearance,app.Mainapp.executableFolder,"Delete");
        end

        % Value changed function: DataTypeDropDown
        function DataTypeDropDownValueChanged(app, event)
            app.Mainapp.CSDApp = app;
            app.ChangeMainPlot = 0;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1;
        end

        % Menu selected function: SVGMenu
        function SVGMenuSelected(app, event)
            [file, path] = uiputfile('*.svg', 'Save as');
            
            exportgraphics(app.CSDWindowUIFigure, fullfile(path,file), 'ContentType', 'vector');
        end

        % Menu selected function: xlsxMenu
        function xlsxMenuSelected(app, event)
            TimeRangePlot = str2double(app.Mainapp.TimeRangeViewBox.Value(1:end-1));
            if strcmp(app.DataTypeDropDown.Value,"Preprocessed Data")
                if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                else
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                end
            else
                StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
            end

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".xlsx",app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Live_CSD_Main_Window",0);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            Utility_Open_UIAxes_as_Figure(app.Mainapp.Data,app.UIAxes,"LiveCSD");
        end

        % Value changed function: CoupleTimetoMainWindowCheckBox
        function CoupleTimetoMainWindowCheckBoxValueChanged(app, event)
            value = app.CoupleTimetoMainWindowCheckBox.Value;
            if value
                if strcmp(app.Mainapp.PlotAppearance.MainWindow.Data.Plottype, "Surf") || strcmp(app.Mainapp.PlotAppearance.MainWindow.Data.Plottype,"Mesh")
                    app.CoupleTimetoMainWindowCheckBox.Value = 0;
                    disp("Disabling couple to main window since just one data point is plotted there in grid activity view mode!");
                    return;
                end
                if strcmp(app.Mainapp.PlotAppearance.MainWindow.Data.Plottype, "AxonViewer")
                    app.CoupleTimetoMainWindowCheckBox.Value = 0;
                    disp("Disabling couple to main window since just one data point is plotted there in axon viewer view mode!")
                    return;
                end

                app.TimeWindowfromtoinsEditField.Enable = "off";
            else
                app.TimeWindowfromtoinsEditField.Enable = "on";
                if isempty(app.TimeWindowfromtoinsEditField.Value)

                    TimeRangePlot = str2double(app.Mainapp.TimeRangeViewBox.Value(1:end-1));
                    if strcmp(app.DataTypeDropDown.Value,"Preprocessed Data")
                        if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                            StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                        else
                            StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                        end
                    else
                        StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                    end

                    app.TimeWindowfromtoinsEditField.Value = strcat(num2str(StartTime),",",num2str(StartTime+TimeRangePlot));
                end
            end

            app.ChangeMainPlot = 0;
            app.Mainapp.CSDApp = app;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1;
        end

        % Value changed function: TimeWindowfromtoinsEditField
        function TimeWindowfromtoinsEditFieldValueChanged(app, event)
            app.ChangeMainPlot = 0;
            app.Mainapp.CSDApp = app;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1;
        end

        % Menu selected function: EPSMenu
        function EPSMenuSelected(app, event)
            [file, path] = uiputfile('*.eps', 'Save as');

            exportgraphics(app.UIAxes, fullfile(path,file), 'ContentType', 'vector')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CSDWindowUIFigure = uifigure('Visible', 'off');
            app.CSDWindowUIFigure.Name = 'Live CSD Window';
            app.CSDWindowUIFigure.Icon = 'Logo.png';
            app.CSDWindowUIFigure.CloseRequestFcn = createCallbackFcn(app, @CSDWindowUIFigureCloseRequest, true);
            app.SnapshotMenu = uimenu(app.CSDWindowUIFigure);
            app.SnapshotMenu.Text = '   Snapshot   ';
            app.SaveSnapshotofWindowMenu = uimenu(app.SnapshotMenu);
            app.SaveSnapshotofWindowMenu.Text = 'Save Snapshot of Window';
            app.PNGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.PNGMenu.MenuSelectedFcn = createCallbackFcn(app, @PNGMenuSelected, true);
            app.PNGMenu.Text = 'PNG';
            app.JPGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.JPGMenu.MenuSelectedFcn = createCallbackFcn(app, @JPGMenuSelected, true);
            app.JPGMenu.Text = 'JPG';
            app.SVGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.SVGMenu.MenuSelectedFcn = createCallbackFcn(app, @SVGMenuSelected, true);
            app.SVGMenu.Text = 'SVG';
            app.EPSMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.EPSMenu.MenuSelectedFcn = createCallbackFcn(app, @EPSMenuSelected, true);
            app.EPSMenu.Text = 'EPS';
            app.PlotMenu = uimenu(app.CSDWindowUIFigure);
            app.PlotMenu.Text = '   Plot   ';
            app.Show2DimensionalPlotsMenu = uimenu(app.PlotMenu);
            app.Show2DimensionalPlotsMenu.MenuSelectedFcn = createCallbackFcn(app, @Show2DimensionalPlotsMenuSelected, true);
            app.Show2DimensionalPlotsMenu.Text = 'Show 2 Dimensional Plots';
            app.Show3DimensionalPlotsMenu = uimenu(app.PlotMenu);
            app.Show3DimensionalPlotsMenu.MenuSelectedFcn = createCallbackFcn(app, @Show3DimensionalPlotsMenuSelected, true);
            app.Show3DimensionalPlotsMenu.Text = 'Show 3 Dimensional Plots';
            app.ChangePlotPropertiesMenu = uimenu(app.PlotMenu);
            app.ChangePlotPropertiesMenu.Text = 'Change Plot Properties';
            app.CSDPlotMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.CSDPlotMenu.Text = 'CSD Plot';
            app.FontSizeMenu = uimenu(app.CSDPlotMenu);
            app.FontSizeMenu.MenuSelectedFcn = createCallbackFcn(app, @FontSizeMenuSelected, true);
            app.FontSizeMenu.Text = 'FontSize';
            app.BackgorundColorMenu = uimenu(app.CSDPlotMenu);
            app.BackgorundColorMenu.MenuSelectedFcn = createCallbackFcn(app, @BackgorundColorMenuSelected, true);
            app.BackgorundColorMenu.Text = 'Backgorund Color';
            app.XLabelMenu = uimenu(app.CSDPlotMenu);
            app.XLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @XLabelMenuSelected, true);
            app.XLabelMenu.Text = 'XLabel';
            app.YLabelMenu = uimenu(app.CSDPlotMenu);
            app.YLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @YLabelMenuSelected, true);
            app.YLabelMenu.Text = 'YLabel';
            app.CLabelMenu = uimenu(app.CSDPlotMenu);
            app.CLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @CLabelMenuSelected, true);
            app.CLabelMenu.Text = 'CLabel';
            app.RESETMenu = uimenu(app.CSDPlotMenu);
            app.RESETMenu.MenuSelectedFcn = createCallbackFcn(app, @RESETMenuSelected, true);
            app.RESETMenu.Text = 'RESET';
            app.SaveCustomeAppearanceMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.SaveCustomeAppearanceMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveCustomeAppearanceMenuSelected, true);
            app.SaveCustomeAppearanceMenu.Text = 'Save Custome Appearance';
            app.DeleteCustomeAppearanceMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.DeleteCustomeAppearanceMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteCustomeAppearanceMenuSelected, true);
            app.DeleteCustomeAppearanceMenu.Text = 'Delete Custome Appearance';
            app.ExportDataMenu = uimenu(app.CSDWindowUIFigure);
            app.ExportDataMenu.Text = '   Export Data   ';
            app.matMenu = uimenu(app.ExportDataMenu);
            app.matMenu.MenuSelectedFcn = createCallbackFcn(app, @matMenuSelected, true);
            app.matMenu.Text = '.mat';
            app.txtMenu = uimenu(app.ExportDataMenu);
            app.txtMenu.MenuSelectedFcn = createCallbackFcn(app, @txtMenuSelected, true);
            app.txtMenu.Text = '.txt';
            app.xlsxMenu = uimenu(app.ExportDataMenu);
            app.xlsxMenu.MenuSelectedFcn = createCallbackFcn(app, @xlsxMenuSelected, true);
            app.xlsxMenu.Text = '.xlsx';
            app.UIAxes = uiaxes(app.CSDWindowUIFigure);
            app.LeftPanel = uipanel(app.CSDWindowUIFigure);
            app.CurrentSourceDensityOptionsPanel = uipanel(app.LeftPanel);
            app.CurrentSourceDensityOptionsPanel.Title = 'Current Source Density Options';
            app.ChannelSelectionfromtoEditFieldLabel = uilabel(app.CurrentSourceDensityOptionsPanel);
            app.ChannelSelectionfromtoEditFieldLabel.Enable = 'off';
            app.ChannelSelectionfromtoEditFieldLabel.Visible = 'off';
            app.ChannelSelectionfromtoEditFieldLabel.Text = {'Channel Selection'; '[from,to]'};
            app.ChannelSelectionfromtoEditField = uieditfield(app.CurrentSourceDensityOptionsPanel, 'text');
            app.ChannelSelectionfromtoEditField.ValueChangedFcn = createCallbackFcn(app, @ChannelSelectionfromtoEditFieldValueChanged2, true);
            app.ChannelSelectionfromtoEditField.Editable = 'off';
            app.ChannelSelectionfromtoEditField.Enable = 'off';
            app.ChannelSelectionfromtoEditField.Visible = 'off';
            app.HammWindowEditFieldLabel = uilabel(app.CurrentSourceDensityOptionsPanel);
            app.HammWindowEditFieldLabel.Text = 'Hamm Window';
            app.HammWindowEditField = uieditfield(app.CurrentSourceDensityOptionsPanel, 'text');
            app.HammWindowEditField.ValueChangedFcn = createCallbackFcn(app, @HammWindowEditFieldValueChanged2, true);
            app.HammWindowEditField.Value = '5';
            app.DataTypeDropDown = uidropdown(app.CurrentSourceDensityOptionsPanel);
            app.DataTypeDropDown.Items = {};
            app.DataTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DataTypeDropDownValueChanged, true);
            app.DataTypeDropDown.Value = {};
            app.DataTypeDropDownLabel = uilabel(app.CurrentSourceDensityOptionsPanel);
            app.DataTypeDropDownLabel.Text = 'Data Type';
            app.CurrentSourceDensityOptionsLabel = uilabel(app.CurrentSourceDensityOptionsPanel);
            app.CurrentSourceDensityOptionsLabel.Text = 'Current Source Density Options';
            app.LockCLimCheckBox = uicheckbox(app.CurrentSourceDensityOptionsPanel);
            app.LockCLimCheckBox.ValueChangedFcn = createCallbackFcn(app, @LockCLimCheckBoxValueChanged, true);
            app.LockCLimCheckBox.Text = '  Lock C Lim';
            app.LockCLimCheckBox.Value = true;
            app.CoupleTimetoMainWindowCheckBox = uicheckbox(app.CurrentSourceDensityOptionsPanel);
            app.CoupleTimetoMainWindowCheckBox.ValueChangedFcn = createCallbackFcn(app, @CoupleTimetoMainWindowCheckBoxValueChanged, true);
            app.CoupleTimetoMainWindowCheckBox.Text = {'  Couple Time'; '  to Main Window'};
            app.CoupleTimetoMainWindowCheckBox.Value = true;
            app.CloseButton = uibutton(app.LeftPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.Button = uibutton(app.CSDWindowUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'Open_Figure_Icon.png';
            app.Button.Text = '';
            app.AnalysisTimeRangefromtoinsEditFieldLabel = uilabel(app.CSDWindowUIFigure);
            app.AnalysisTimeRangefromtoinsEditFieldLabel.Text = {'Analysis Time'; 'Range '; '[from, to] in s'};
            app.TimeWindowfromtoinsEditField = uieditfield(app.CSDWindowUIFigure, 'text');
            app.TimeWindowfromtoinsEditField.ValueChangedFcn = createCallbackFcn(app, @TimeWindowfromtoinsEditFieldValueChanged, true);
            app.TimeWindowfromtoinsEditField.Enable = 'off';
            % Show the figure after all components are created
            app.CSDWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CSD_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.CSDWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.CSDWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CSDWindowUIFigure)
        end
    end
end