classdef Live_InstFrequency_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InstantaneousFrequencyUIFigure  matlab.ui.Figure
        SnapshotMenu                    matlab.ui.container.Menu
        SaveSnapshotofWindowMenu        matlab.ui.container.Menu
        PNGMenu                         matlab.ui.container.Menu
        JPGMenu                         matlab.ui.container.Menu
        SVGMenu                         matlab.ui.container.Menu
        InstantaneousPhasePlotMenu      matlab.ui.container.Menu
        InstantaneousFrequencyPlotMenu  matlab.ui.container.Menu
        PhaseSyncAllChannelPlotMenu     matlab.ui.container.Menu
        PhaseSyncPolarPlotMenu          matlab.ui.container.Menu
        EPSMenu                         matlab.ui.container.Menu
        InstantaneousPhasePlotMenu_2    matlab.ui.container.Menu
        InstantaneousFrequencyPlotMenu_2  matlab.ui.container.Menu
        PhaseSyncAllChannelPlotMenu_2   matlab.ui.container.Menu
        PhaseSyncPolarPlotMenu_2        matlab.ui.container.Menu
        PlotMenu                        matlab.ui.container.Menu
        ChangePlotPropertiesMenu        matlab.ui.container.Menu
        AlltoAllSyncPlotMenu            matlab.ui.container.Menu
        FontSizeMenu                    matlab.ui.container.Menu
        XLabelMenu                      matlab.ui.container.Menu
        YLabelMenu                      matlab.ui.container.Menu
        CLabelMenu                      matlab.ui.container.Menu
        TitleMenu                       matlab.ui.container.Menu
        RESETMenu                       matlab.ui.container.Menu
        PhaseAngleDifferencePlotMenu    matlab.ui.container.Menu
        FontSizeMenu_2                  matlab.ui.container.Menu
        ColorPhaseAngleDifferencesMenu  matlab.ui.container.Menu
        ColorAveragePhaseVectorMenu     matlab.ui.container.Menu
        LineWidthPhaseAngleDifferencesMenu  matlab.ui.container.Menu
        LineWidthAveragePhaseVectorMenu  matlab.ui.container.Menu
        BackgorundColorMenu             matlab.ui.container.Menu
        TitleMenu_2                     matlab.ui.container.Menu
        RESETMenu_2                     matlab.ui.container.Menu
        PhaseAnglesPlotMenu             matlab.ui.container.Menu
        FontSizeMenu_3                  matlab.ui.container.Menu
        ColormapMenu                    matlab.ui.container.Menu
        LineWidthPhaseAnglesMenu        matlab.ui.container.Menu
        BackgorundColorMenu_2           matlab.ui.container.Menu
        XLabelMenu_2                    matlab.ui.container.Menu
        YLabelMenu_2                    matlab.ui.container.Menu
        TitleMenu_3                     matlab.ui.container.Menu
        RESETMenu_3                     matlab.ui.container.Menu
        PhaseEnvelopePlotMenu           matlab.ui.container.Menu
        FontSizeMenu_4                  matlab.ui.container.Menu
        ColormapMenu_2                  matlab.ui.container.Menu
        LineWidthPhaseAngleEnvelopesMenu  matlab.ui.container.Menu
        BackgorundColorMenu_3           matlab.ui.container.Menu
        XLabelMenu_3                    matlab.ui.container.Menu
        YLabelMenu_3                    matlab.ui.container.Menu
        TitleMenu_4                     matlab.ui.container.Menu
        RESETMenu_4                     matlab.ui.container.Menu
        SaveCustomeAppearanceMenu       matlab.ui.container.Menu
        DeleteCustomeAppearanceMenu     matlab.ui.container.Menu
        ExportDataMenu                  matlab.ui.container.Menu
        PhaseAngleTimeSeriesMenu        matlab.ui.container.Menu
        matMenu_2                       matlab.ui.container.Menu
        txtMenu_2                       matlab.ui.container.Menu
        PhaseAngleAmplitudeEnvelopeMenu  matlab.ui.container.Menu
        matMenu_3                       matlab.ui.container.Menu
        txtMenu_3                       matlab.ui.container.Menu
        AllToAllSynchronizationMenu     matlab.ui.container.Menu
        matMenu_4                       matlab.ui.container.Menu
        txtMenu_4                       matlab.ui.container.Menu
        PolarPhaseAngleDifferencesMenu  matlab.ui.container.Menu
        matMenu_5                       matlab.ui.container.Menu
        txtMenu_5                       matlab.ui.container.Menu
        ExtrasMenu                      matlab.ui.container.Menu
        ChangeLowPassParameterMenu      matlab.ui.container.Menu
        ShowDataAnalysedMenu            matlab.ui.container.Menu
        ONMenu                          matlab.ui.container.Menu
        OFFMenu                         matlab.ui.container.Menu
        ChangeFilterTypeMenu            matlab.ui.container.Menu
        ButterworthMenu                 matlab.ui.container.Menu
        FIRMenu                         matlab.ui.container.Menu
        TextArea                        matlab.ui.control.TextArea
        vsLabel                         matlab.ui.control.Label
        ChannelSelectionDropDown_2      matlab.ui.control.DropDown
        ChannelSelectionDropDown        matlab.ui.control.DropDown
        ChannelSelectionDropDownLabel   matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        SettingsLabel                   matlab.ui.control.Label
        CloseButton                     matlab.ui.control.Button
        SettingsPanel                   matlab.ui.container.Panel
        TimeWindowfromtoinsEditField    matlab.ui.control.EditField
        TimeWindowfromtoinsEditFieldLabel  matlab.ui.control.Label
        CoupleTimetoMainWindowCheckBox  matlab.ui.control.CheckBox
        ECHTFilterorderLabel            matlab.ui.control.Label
        ECHTFilterorderEditField        matlab.ui.control.EditField
        ForceFilterOFFCheckBox          matlab.ui.control.CheckBox
        DataTypeDropDown                matlab.ui.control.DropDown
        DataTypeDropDownLabel           matlab.ui.control.Label
        CalculationMethodDropDown       matlab.ui.control.DropDown
        CalculationMethodDropDownLabel  matlab.ui.control.Label
        LockYlimCheckBox                matlab.ui.control.CheckBox
        NarrowbandFilterorderEditField  matlab.ui.control.EditField
        NarrowbandFilterorderLabel      matlab.ui.control.Label
        NarrowbandCutoffLowerHigherEditField  matlab.ui.control.EditField
        NarrowbandCutoffLowerHigherEditFieldLabel  matlab.ui.control.Label
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Mainapp = []; % Description
        ChannelToCompare = [];
        ExistflagECHT = [];
        Startup = 1
        GlobalYlim = [];
        PolarPlot = [];
        WhatToDo = 'All';
        Ccolormap = [];
        ShowAnayzedData = 0;
        ChangeFilterSettingsWindow = [];
        
        FilterType = 'FIR';
        LowPassSettings = [];

        ChangeMainPlot = 1;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            %% ------------------- Set Up Window -------------------
            %  -----------------------------------------------------
            %center
            movegui(app.InstantaneousFrequencyUIFigure, 'north');
            
            %get access to main app data, set flags that this window is
            %opened and save this app as part of the main window for access
            app.Mainapp = Mainapp;
            
            
            % Check whether raw data is present
            if ~isfield(app.Mainapp.Data,'Raw') 
                msgbox("Error: No data detected. Please first extract data from a recording!");
                app.Mainapp.LiveECHTWindow = [];
                delete(app);
            end
            
            % set up window components colors
            app = Utility_Change_Light_Dark_Mode(app,"InstFrequWindow");
            if strcmp(app.Mainapp.PlotAppearance.MainWindow.Data.Plottype, "Surf") || strcmp(app.Mainapp.PlotAppearance.MainWindow.Data.Plottype,"Mesh")
                app.CoupleTimetoMainWindowCheckBox.Value = 0;
                disp("Disabling couple to main window since just one data point is plotted there in grid activity view mode!");
                app.TimeWindowfromtoinsEditField.Enable = "on";
                if isempty(app.TimeWindowfromtoinsEditField.Value)
                    
                    TimeRangePlot = 1.5;
                    if contains(app.DataTypeDropDown.Value,"Preprocessed Data")
                        if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                            StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                        else
                            StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                        end
                    else
                        StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                    end
                    
                    if StartTime<app.Mainapp.Data.Time(end) || StartTime+TimeRangePlot<app.Mainapp.Data.Time(end)
                        app.TimeWindowfromtoinsEditField.Value = strcat(num2str(StartTime),",",num2str(StartTime+TimeRangePlot));
                    else
                        StartTime = app.Mainapp.Data.Time(end)-1;
                        TimeRangePlot = 0.9;
                        app.TimeWindowfromtoinsEditField.Value = strcat(num2str(StartTime),",",num2str(StartTime+TimeRangePlot));
                    end
                end
            end
            if strcmp(app.Mainapp.PlotAppearance.MainWindow.Data.Plottype, "AxonViewer")
                app.CoupleTimetoMainWindowCheckBox.Value = 0;
                disp("Disabling couple to main window since just one data point is plotted there in axon viewer view mode!")
                app.TimeWindowfromtoinsEditField.Enable = "on";
                if isempty(app.TimeWindowfromtoinsEditField.Value)

                    TimeRangePlot = 1.5;
                    if contains(app.DataTypeDropDown.Value,"Preprocessed Data")
                        if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                            StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                        else
                            StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                        end
                    else
                        StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                    end

                    if StartTime<app.Mainapp.Data.Time(end) || StartTime+TimeRangePlot<app.Mainapp.Data.Time(end)
                        app.TimeWindowfromtoinsEditField.Value = strcat(num2str(StartTime),",",num2str(StartTime+TimeRangePlot));
                    else
                        StartTime = app.Mainapp.Data.Time(end)-1;
                        TimeRangePlot = 0.9;
                        app.TimeWindowfromtoinsEditField.Value = strcat(num2str(StartTime),",",num2str(StartTime+TimeRangePlot));
                    end
                end
            end
            
            app.ExistflagECHT = 1;
            app.Mainapp.LiveECHTWindow = app;

            % set up text are information
            %app.InformationTextArea.Value = Utility_Show_Narrowband_FilterStatusMessage(app.Mainapp.Data,app.InformationTextArea.Value,app.DataTypeDropDown.Value);
            % automatically find filter order and populate filter order
            % text area
            freqs = str2double(strsplit(app.NarrowbandCutoffLowerHigherEditField.Value,','));
            
            [app.NarrowbandFilterorderEditField.Value] = Utility_Determine_FIR_FilterOrder(app,freqs(1));
            
            % if app.NarrowbandFilterorderEditField.Value
            % 
            % end
            % Set up polar plot
            app.PolarPlot = polaraxes('Parent', app.Panel);
            app.Ccolormap = parula(size(app.Mainapp.Data.Raw,1));

            % set up filter options
            app.ButterworthMenu.ForegroundColor = [1,0,0];
            app.FIRMenu.ForegroundColor = [0,1,0];
            
            % set up current status to know what to compute and plot (all to all connectivity and phase angle differences not always computed and plotted again!)
            app.WhatToDo = 'Startup';

            % standard settings
            app.LowPassSettings.Cutoff = 300;
            app.LowPassSettings.FilterOrder = 4;

            if isfield(app.Mainapp.Data,'Preprocessed')
                app.DataTypeDropDown.Items{2} = 'Preprocessed Data';
            end

            %% ------------------- Set Up Channel -------------------
            %  -----------------------------------------------------
            AllChannels = {};
            if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 1
                TempActiveChannel = (str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)*str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)+1)-sort(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
                for i = 1:length(TempActiveChannel)
                    AllChannels{i} = num2str(TempActiveChannel(i));
                end
            else
                for i = 1:length(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel)
                    AllChannels{i} = num2str(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel(i));
                end
            end

            app.ChannelSelectionDropDown.Items = AllChannels;
            app.ChannelSelectionDropDown_2.Items = AllChannels;
            app.ChannelSelectionDropDown_2.Value = app.ChannelSelectionDropDown_2.Items{2};
            
            app.ECHTFilterorderEditField.Enable = "off";

            app.ChannelToCompare = [str2double(app.ChannelSelectionDropDown.Value),str2double(app.ChannelSelectionDropDown_2.Value)];

            %% ------------------- Calculate and Plot -------------------
            %  -----------------------------------------------------
            app.ChangeMainPlot = 0; 
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1; 
            app.Startup = 0;
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            
            try
                app.ChangeMainPlot = 1; 
                if ~isempty(app.Mainapp.ProbeViewWindowHandle) % delete option from probe view 
                    for i = 1:length(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                        if strcmp(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Main Plot Phase Synchronization')
                            app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items(i) = [];
                            break;
                        end
                    end
                else % if no probe view available
     
                end

                try
                    if ~isempty(app.ChangeFilterSettingsWindow)
                        delete(app.ChangeFilterSettingsWindow);
                    end
                end
                app.ExistflagECHT = 0;
                app.Mainapp.LiveECHTWindow = [];
                delete(app)
            catch
                delete(app)
            end
        end

        % Close request function: InstantaneousFrequencyUIFigure
        function InstantaneousFrequencyUIFigureCloseRequest(app, event)
            
            try
                app.ChangeMainPlot = 1; 
                if ~isempty(app.Mainapp.ProbeViewWindowHandle) % delete option from probe view 
                    for i = 1:length(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                        if strcmp(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Main Plot Phase Synchronization')
                            app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items(i) = [];
                            break;
                        end
                    end
                else % if no probe view available
     
                end

                try
                    if ~isempty(app.ChangeFilterSettingsWindow)
                        delete(app.ChangeFilterSettingsWindow);
                    end
                end
                app.ExistflagECHT = 0;
                app.Mainapp.LiveECHTWindow = [];
                delete(app)
            catch
                delete(app)
            end
        end

        % Value changed function: ChannelSelectionDropDown
        function ChannelSelectionDropDownValueChanged(app, event)
            app.ChannelToCompare = [str2double(app.ChannelSelectionDropDown.Value),str2double(app.ChannelSelectionDropDown_2.Value)];
            
            app.WhatToDo = 'ChannelChange';
            app.ChangeMainPlot = 0; 
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1; 
            app.WhatToDo = 'All';
        end

        % Value changed function: NarrowbandCutoffLowerHigherEditField
        function NarrowbandCutoffLowerHigherEditFieldValueChanged(app, event)
            if isempty(app.NarrowbandCutoffLowerHigherEditField.Value)
                app.NarrowbandCutoffLowerHigherEditField.Value = event.PreviousValue;
            end

            freqs = str2double(strsplit(app.NarrowbandCutoffLowerHigherEditField.Value,','));
            
            [app.NarrowbandFilterorderEditField.Value] = Utility_Determine_FIR_FilterOrder(app,freqs(1));
            
            app.WhatToDo = 'All';
            app.ChangeMainPlot = 0; 
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1; 
        end

        % Value changed function: NarrowbandFilterorderEditField
        function NarrowbandFilterorderEditFieldValueChanged(app, event)
            app.WhatToDo = 'All';
            
            if isempty(app.NarrowbandFilterorderEditField.Value)
                app.NarrowbandFilterorderEditField.Value = event.PreviousValue;
            else
                if str2double(app.NarrowbandFilterorderEditField.Value) < 0 || isnan(str2double(app.NarrowbandFilterorderEditField.Value))
                    app.NarrowbandFilterorderEditField.Value = event.PreviousValue;
                end
            end
            app.ChangeMainPlot = 0; 
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1; 
        end

        % Value changed function: ChannelSelectionDropDown_2
        function ChannelSelectionDropDown_2ValueChanged(app, event)
            
            app.ChannelToCompare = [str2double(app.ChannelSelectionDropDown.Value),str2double(app.ChannelSelectionDropDown_2.Value)];
            
            app.WhatToDo = 'ChannelChange';
            app.ChangeMainPlot = 0; 
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1; 
            app.WhatToDo = 'All';
        end

        % Value changed function: DataTypeDropDown
        function DataTypeDropDownValueChanged(app, event)
            value = app.DataTypeDropDown.Value;
            app.WhatToDo = 'All';
            app.ChangeMainPlot = 0; 
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1; 
        end

        % Value changed function: CalculationMethodDropDown
        function CalculationMethodDropDownValueChanged(app, event)
            app.WhatToDo = 'All';
            
            if strcmp(app.CalculationMethodDropDown.Value,"Endpoint Corrected Hilbert Transform")
                app.ECHTFilterorderEditField.Enable = "on";
                app.ECHTFilterorderLabel.Text = "ECHT Filterorder";
                %app.ECHTFilterorderEditField.Value = '2';
            elseif strcmp(app.CalculationMethodDropDown.Value,"Hilbert Transform")
                app.ECHTFilterorderEditField.Enable = "off";
            elseif strcmp(app.CalculationMethodDropDown.Value,"Wavelet Convolution")
                app.ECHTFilterorderEditField.Enable = "on";
                app.ECHTFilterorderLabel.Text = "Number Cycles";
                %app.ECHTFilterorderEditField.Value = '20';
            end
            app.ChangeMainPlot = 0; 
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1; 
        end

        % Menu selected function: PNGMenu
        function PNGMenuSelected(app, event)
            [file, path] = uiputfile('*.png', 'Save as');

            exportapp(app.InstantaneousFrequencyUIFigure,fullfile(path,file)) 
        end

        % Menu selected function: JPGMenu
        function JPGMenuSelected(app, event)
            [file, path] = uiputfile('*.jpg', 'Save as');

            exportapp(app.InstantaneousFrequencyUIFigure,fullfile(path,file))
        end

        % Menu selected function: FontSizeMenu
        function FontSizeMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes_3,'Font Size',app.Mainapp,"MainDataphasesyncAllFontSize");
            waitfor(h);
            
            cla(app.UIAxes_3)
            cla(app.PolarPlot)

            app.WhatToDo = 'AllToAllDifferences';
            app.ChangeMainPlot = 0; 
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1; 
            app.WhatToDo = 'All';
        end

        % Menu selected function: XLabelMenu
        function XLabelMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes_3,'X Label',app.Mainapp,"MainDataphasesyncAllXLabel");
            waitfor(h);
            
            cla(app.UIAxes_3)
            cla(app.PolarPlot)

            app.WhatToDo = 'AllToAllDifferences';
            app.ChangeMainPlot = 0; 
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1; 
            app.WhatToDo = 'All';
        end

        % Menu selected function: YLabelMenu
        function YLabelMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes_3,'Y Label',app.Mainapp,"MainDataphasesyncAllYLabel");
            waitfor(h);
            
            cla(app.UIAxes_3)
            cla(app.PolarPlot)

            app.WhatToDo = 'AllToAllDifferences';
            app.ChangeMainPlot = 0; 
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1; 
            app.WhatToDo = 'All';
        end

        % Menu selected function: CLabelMenu
        function CLabelMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes_3,'C Label',app.Mainapp,"MainDataphasesyncCLabel");
            waitfor(h);

            cla(app.UIAxes_3)
            cla(app.PolarPlot)

            app.WhatToDo = 'AllToAllDifferences';
            app.ChangeMainPlot = 0; 
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1; 
            app.WhatToDo = 'All';
        end

        % Menu selected function: TitleMenu
        function TitleMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes_3,'Title',app.Mainapp,"MainDataphasesyncAllTitle");
            waitfor(h);

            cla(app.UIAxes_3)
            cla(app.PolarPlot)

            app.WhatToDo = 'AllToAllDifferences';
            app.ChangeMainPlot = 0; 
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1; 
            app.WhatToDo = 'All';
        end

        % Menu selected function: RESETMenu
        function RESETMenuSelected(app, event)
            [app.Mainapp.PlotAppearance] = Organize_Set_Standard_PlotAppearance("PhaseSyncPlotAllToAll",app.Mainapp.PlotAppearance);
            
            cla(app.UIAxes_3)
            cla(app.PolarPlot)

            app.WhatToDo = 'AllToAllDifferences';
            app.ChangeMainPlot = 0; 
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1; 
            app.WhatToDo = 'All';
        end

        % Menu selected function: FontSizeMenu_2
        function FontSizeMenu_2Selected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes_3,'Font Size',app.Mainapp,"MainDataphasesyncDiffsFontSize");
            waitfor(h);
            
            cla(app.UIAxes_3)
            cla(app.PolarPlot)

            app.WhatToDo = 'AllToAllDifferences';
            app.ChangeMainPlot = 0; 
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1; 
            app.WhatToDo = 'All';
        end

        % Menu selected function: ColorPhaseAngleDifferencesMenu
        function ColorPhaseAngleDifferencesMenuSelected(app, event)
            cla(app.UIAxes_3)
            cla(app.PolarPlot)

            selectedColor = uisetcolor; 
            
            if length(selectedColor) == 3
                app.Mainapp.PlotAppearance.PhaseSyncPlots.AngleDiffAnglesColor = selectedColor;   
            end
            
            app.WhatToDo = 'AllToAllDifferences';
            app.ChangeMainPlot = 0; 
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1; 
            app.WhatToDo = 'All';
        end

        % Menu selected function: ColorAveragePhaseVectorMenu
        function ColorAveragePhaseVectorMenuSelected(app, event)
            cla(app.UIAxes_3)
            cla(app.PolarPlot)

            selectedColor = uisetcolor; 
            
            if length(selectedColor) == 3
                app.Mainapp.PlotAppearance.PhaseSyncPlots.AngleDiffMeanColor = selectedColor;   
            end

            app.WhatToDo = 'AllToAllDifferences';
            app.ChangeMainPlot = 0; 
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1; 
            app.WhatToDo = 'All';
        end

        % Menu selected function: LineWidthPhaseAngleDifferencesMenu
        function LineWidthPhaseAngleDifferencesMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes_3,'Linewidth',app.Mainapp,"MainDataphasesyncDiffsLineWidthPhases");
            waitfor(h);
            
            cla(app.UIAxes_3)
            cla(app.PolarPlot)

            app.WhatToDo = 'AllToAllDifferences';
            app.ChangeMainPlot = 0; 
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1; 
            app.WhatToDo = 'All';
        end

        % Menu selected function: LineWidthAveragePhaseVectorMenu
        function LineWidthAveragePhaseVectorMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes_3,'Linewidth',app.Mainapp,"MainDataphasesyncDiffsLineWidthAverage");
            waitfor(h);
            
            cla(app.UIAxes_3)
            cla(app.PolarPlot)

            app.WhatToDo = 'AllToAllDifferences';
            app.ChangeMainPlot = 0; 
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1; 
            app.WhatToDo = 'All';
        end

        % Menu selected function: BackgorundColorMenu
        function BackgorundColorMenuSelected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.Mainapp.PlotAppearance.PhaseSyncPlots.AngleDiffBackgroundColor = selectedColor;
                app.PolarPlot.Color = app.Mainapp.PlotAppearance.PhaseSyncPlots.AngleDiffBackgroundColor;
            end
        end

        % Menu selected function: TitleMenu_2
        function TitleMenu_2Selected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes_3,'Title',app.Mainapp,"MainDataphasesyncDiffsTitle");
            waitfor(h);
            
            cla(app.UIAxes_3)
            cla(app.PolarPlot)

            app.WhatToDo = 'AllToAllDifferences';
            app.ChangeMainPlot = 0; 
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1; 
            app.WhatToDo = 'All';
        end

        % Menu selected function: RESETMenu_2
        function RESETMenu_2Selected(app, event)
            [app.Mainapp.PlotAppearance] = Organize_Set_Standard_PlotAppearance("PhaseSyncPlotPhasDiffs",app.Mainapp.PlotAppearance);
            
            cla(app.UIAxes_3)
            cla(app.PolarPlot)

            app.WhatToDo = 'AllToAllDifferences';
            app.ChangeMainPlot = 0; 
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1; 
            app.WhatToDo = 'All';
        end

        % Menu selected function: SaveCustomeAppearanceMenu
        function SaveCustomeAppearanceMenuSelected(app, event)
            app.Mainapp.PlotAppearance = Utility_Save_Load_Delete_Plot_Appearance(app.Mainapp.PlotAppearance,app.Mainapp.executableFolder,"Save");
        end

        % Menu selected function: DeleteCustomeAppearanceMenu
        function DeleteCustomeAppearanceMenuSelected(app, event)
            app.Mainapp.PlotAppearance = Utility_Save_Load_Delete_Plot_Appearance(app.Mainapp.PlotAppearance,app.Mainapp.executableFolder,"Delete");
        end

        % Value changed function: ForceFilterOFFCheckBox
        function ForceFilterOFFCheckBoxValueChanged(app, event)
            value = app.ForceFilterOFFCheckBox.Value;
            app.WhatToDo = 'All';
            app.ChangeMainPlot = 0; 
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1; 
        end

        % Menu selected function: FontSizeMenu_3
        function FontSizeMenu_3Selected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes_3,'Font Size',app.Mainapp,"MainDataPhaseAnglesFontSize");
            waitfor(h);
            
            cla(app.UIAxes_2)
            cla(app.UIAxes)

            app.WhatToDo = 'AnglesEnvelops';
            app.ChangeMainPlot = 0; 
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1; 
            app.WhatToDo = 'All';
        end

        % Menu selected function: XLabelMenu_2
        function XLabelMenu_2Selected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes_3,'X Label',app.Mainapp,"MainDataPhaseAnglesXLabel");
            waitfor(h);
            
            cla(app.UIAxes_2)
            cla(app.UIAxes)

            app.WhatToDo = 'AnglesEnvelops';
            app.ChangeMainPlot = 0; 
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1; 
            app.WhatToDo = 'All';
        end

        % Menu selected function: YLabelMenu_2
        function YLabelMenu_2Selected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes_3,'Y Label',app.Mainapp,"MainDataPhaseAnglesYLabel");
            waitfor(h);
            
            cla(app.UIAxes_2)
            cla(app.UIAxes)

            app.WhatToDo = 'AnglesEnvelops';
            app.ChangeMainPlot = 0; 
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1; 
            app.WhatToDo = 'All';
        end

        % Menu selected function: TitleMenu_3
        function TitleMenu_3Selected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes_3,'Title',app.Mainapp,"MainDataPhaseAnglesTitle");
            waitfor(h);

            cla(app.UIAxes_2)
            cla(app.UIAxes)

            app.WhatToDo = 'AnglesEnvelops';
            app.ChangeMainPlot = 0; 
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1; 
            app.WhatToDo = 'All';
        end

        % Menu selected function: LineWidthPhaseAnglesMenu
        function LineWidthPhaseAnglesMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes_3,'Linewidth',app.Mainapp,"MainDataPhaseAnglesLineWidthPhases");
            waitfor(h);
            
            cla(app.UIAxes_2)
            cla(app.UIAxes)

            app.WhatToDo = 'AnglesEnvelops';
            app.ChangeMainPlot = 0; 
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1; 
            app.WhatToDo = 'All';
        end

        % Menu selected function: BackgorundColorMenu_2
        function BackgorundColorMenu_2Selected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.Mainapp.PlotAppearance.PhaseSyncPlots.PATimeSeriesBackgroundColor = selectedColor;
                app.UIAxes.Color = app.Mainapp.PlotAppearance.PhaseSyncPlots.PATimeSeriesBackgroundColor;
            end
        end

        % Menu selected function: FontSizeMenu_4
        function FontSizeMenu_4Selected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes_3,'Font Size',app.Mainapp,"MainDataPhaseEnvelopsFontSize");
            waitfor(h);
            
            cla(app.UIAxes_2)
            cla(app.UIAxes)

            app.WhatToDo = 'AnglesEnvelops';
            app.ChangeMainPlot = 0; 
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1; 
            app.WhatToDo = 'All';
        end

        % Menu selected function: LineWidthPhaseAngleEnvelopesMenu
        function LineWidthPhaseAngleEnvelopesMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes_3,'Linewidth',app.Mainapp,"MainDataPhaseEnvelopsLineWidthPhases");
            waitfor(h);
            
            cla(app.UIAxes_2)
            cla(app.UIAxes)

            app.WhatToDo = 'AnglesEnvelops';
            app.ChangeMainPlot = 0; 
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1; 
            app.WhatToDo = 'All';
        end

        % Menu selected function: BackgorundColorMenu_3
        function BackgorundColorMenu_3Selected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.Mainapp.PlotAppearance.PhaseSyncPlots.PAAmpBackgroundColor = selectedColor;
                app.UIAxes_2.Color = app.Mainapp.PlotAppearance.PhaseSyncPlots.PAAmpBackgroundColor;
            end
        end

        % Menu selected function: XLabelMenu_3
        function XLabelMenu_3Selected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes_3,'X Label',app.Mainapp,"MainDataPhaseEnvelopsXLabel");
            waitfor(h);
            
            cla(app.UIAxes_2)
            cla(app.UIAxes)

            app.WhatToDo = 'AnglesEnvelops';
            app.ChangeMainPlot = 0; 
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1; 
            app.WhatToDo = 'All';
        end

        % Menu selected function: YLabelMenu_3
        function YLabelMenu_3Selected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes_3,'Y Label',app.Mainapp,"MainDataPhaseEnvelopsYLabel");
            waitfor(h);
            
            cla(app.UIAxes_2)
            cla(app.UIAxes)

            app.WhatToDo = 'AnglesEnvelops';
            app.ChangeMainPlot = 0; 
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1; 
            app.WhatToDo = 'All';
        end

        % Menu selected function: TitleMenu_4
        function TitleMenu_4Selected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes_3,'Title',app.Mainapp,"MainDataPhaseEnvelopsTitle");
            waitfor(h);

            cla(app.UIAxes_2)
            cla(app.UIAxes)

            app.WhatToDo = 'AnglesEnvelops';
            app.ChangeMainPlot = 0; 
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1; 
            app.WhatToDo = 'All';
        end

        % Menu selected function: RESETMenu_3
        function RESETMenu_3Selected(app, event)
            [app.Mainapp.PlotAppearance] = Organize_Set_Standard_PlotAppearance("PhaseAngleTimeSeries",app.Mainapp.PlotAppearance);
            
            cla(app.UIAxes_2)
            cla(app.UIAxes)

            app.WhatToDo = 'AnglesEnvelops';
            app.ChangeMainPlot = 0; 
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1; 
            app.WhatToDo = 'All';
        end

        % Menu selected function: RESETMenu_4
        function RESETMenu_4Selected(app, event)
            [app.Mainapp.PlotAppearance] = Organize_Set_Standard_PlotAppearance("PhaseAngleAmplitude",app.Mainapp.PlotAppearance);
            
            cla(app.UIAxes_2)
            cla(app.UIAxes)

            app.WhatToDo = 'AnglesEnvelops';
            app.ChangeMainPlot = 0; 
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1; 
            app.WhatToDo = 'All';
        end

        % Menu selected function: ColormapMenu
        function ColormapMenuSelected(app, event)
            
        end

        % Menu selected function: ColormapMenu_2
        function ColormapMenu_2Selected(app, event)
            
        end

        % Value changed function: ECHTFilterorderEditField
        function ECHTFilterorderEditFieldValueChanged(app, event)
            if isempty(app.ECHTFilterorderEditField.Value)
                app.ECHTFilterorderEditField.Value = event.PreviousValue;
            else
                if str2double(app.ECHTFilterorderEditField.Value) < 0 || isnan(str2double(app.ECHTFilterorderEditField.Value))
                    app.ECHTFilterorderEditField.Value = event.PreviousValue;
                end
            end

            app.WhatToDo = 'All';
            app.ChangeMainPlot = 0; 
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1; 
        end

        % Menu selected function: matMenu_2
        function matMenu_2Selected(app, event)
            TimeRangePlot = str2double(app.Mainapp.TimeRangeViewBox.Value(1:end-1));
            if contains(app.DataTypeDropDown.Value,"Preprocessed Data")
                if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                else
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                end
            else
                StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
            end

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Instantaneous Live Instantaneous Frequency Phase Time Series",0);
        end

        % Menu selected function: matMenu_3
        function matMenu_3Selected(app, event)
            TimeRangePlot = str2double(app.Mainapp.TimeRangeViewBox.Value(1:end-1));
            if contains(app.DataTypeDropDown.Value,"Preprocessed Data")
                if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                else
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                end
            else
                StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
            end

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Instantaneous Phase Amplitude Envelope",0);
        end

        % Menu selected function: matMenu_4
        function matMenu_4Selected(app, event)
            TimeRangePlot = str2double(app.Mainapp.TimeRangeViewBox.Value(1:end-1));
            if contains(app.DataTypeDropDown.Value,"Preprocessed Data")
                if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                else
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                end
            else
                StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
            end

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Instantaneous All To All Connectivity",0);
        end

        % Menu selected function: matMenu_5
        function matMenu_5Selected(app, event)
            TimeRangePlot = str2double(app.Mainapp.TimeRangeViewBox.Value(1:end-1));
            if contains(app.DataTypeDropDown.Value,"Preprocessed Data")
                if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                else
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                end
            else
                StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
            end

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Instantaneous Polar Phase Angle Differences",0);
        end

        % Menu selected function: txtMenu_2
        function txtMenu_2Selected(app, event)
            TimeRangePlot = str2double(app.Mainapp.TimeRangeViewBox.Value(1:end-1));
            if contains(app.DataTypeDropDown.Value,"Preprocessed Data")
                if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                else
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                end
            else
                StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
            end
            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Instantaneous Live Instantaneous Frequency Phase Time Series",0);
        end

        % Menu selected function: txtMenu_3
        function txtMenu_3Selected(app, event)
            TimeRangePlot = str2double(app.Mainapp.TimeRangeViewBox.Value(1:end-1));
            if contains(app.DataTypeDropDown.Value,"Preprocessed Data")
                if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                else
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                end
            else
                StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
            end

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Instantaneous Phase Amplitude Envelope",0);
        end

        % Menu selected function: txtMenu_4
        function txtMenu_4Selected(app, event)
            TimeRangePlot = str2double(app.Mainapp.TimeRangeViewBox.Value(1:end-1));
            if contains(app.DataTypeDropDown.Value,"Preprocessed Data")
                if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                else
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                end
            else
                StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
            end

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Instantaneous All To All Connectivity",0);
        end

        % Menu selected function: txtMenu_5
        function txtMenu_5Selected(app, event)
            TimeRangePlot = str2double(app.Mainapp.TimeRangeViewBox.Value(1:end-1));
            if contains(app.DataTypeDropDown.Value,"Preprocessed Data")
                if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                else
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                end
            else
                StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
            end

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Instantaneous Polar Phase Angle Differences",0);
        end

        % Menu selected function: ONMenu
        function ONMenuSelected(app, event)
            app.ShowAnayzedData = 1;

            app.WhatToDo = 'AnglesEnvelops';
            app.ChangeMainPlot = 0; 
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1; 
            app.WhatToDo = 'All';
        end

        % Menu selected function: OFFMenu
        function OFFMenuSelected(app, event)
            app.ShowAnayzedData = 0;

            app.WhatToDo = 'AnglesEnvelops';
            app.ChangeMainPlot = 0; 
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1; 
            app.WhatToDo = 'All';
        end

        % Menu selected function: ChangeLowPassParameterMenu
        function ChangeLowPassParameterMenuSelected(app, event)
            app.ChangeFilterSettingsWindow = Change_Filter_Options_Window(app,app.Mainapp,"LowPass");

            uiwait(app.ChangeFilterSettingsWindow.ChangeFilterOptionsUIFigure);

            if isvalid(app.ChangeFilterSettingsWindow)
                if ~isempty(app.ChangeFilterSettingsWindow)
                    delete(app.ChangeFilterSettingsWindow)
                    msgbox(strcat('Low Pass filter settings succesfully changed to cutoff: ',num2str(app.LowPassSettings.Cutoff),'; filter order: ',num2str(app.LowPassSettings.FilterOrder)))
                end
            end

            app.WhatToDo = 'All';
            
            app.ChangeMainPlot = 0; 
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1; 
            
        end

        % Menu selected function: FIRMenu
        function FIRMenuSelected(app, event)
            app.FilterType = 'FIR';

            freqs = str2double(strsplit(app.NarrowbandCutoffLowerHigherEditField.Value,','));
            
            [app.NarrowbandFilterorderEditField.Value] = Utility_Determine_FIR_FilterOrder(app,freqs(1));
            
            % set up filter options
            app.ButterworthMenu.ForegroundColor = [1,0,0];
            app.FIRMenu.ForegroundColor = [0,1,0];

            app.WhatToDo = 'All';
            
            app.ChangeMainPlot = 0; 
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1; 
        end

        % Menu selected function: ButterworthMenu
        function ButterworthMenuSelected(app, event)
            app.FilterType = 'Butter';
            
            app.NarrowbandFilterorderEditField.Value = '4';

            app.WhatToDo = 'All';

            % set up filter options
            app.ButterworthMenu.ForegroundColor = [0,1,0];
            app.FIRMenu.ForegroundColor = [1,0,0];
            
            app.ChangeMainPlot = 0; 
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1; 
        end

        % Value changed function: CoupleTimetoMainWindowCheckBox
        function CoupleTimetoMainWindowCheckBoxValueChanged(app, event)
            value = app.CoupleTimetoMainWindowCheckBox.Value;
            if value
                if strcmp(app.Mainapp.PlotAppearance.MainWindow.Data.Plottype, "Surf") || strcmp(app.Mainapp.PlotAppearance.MainWindow.Data.Plottype,"Mesh")
                    app.CoupleTimetoMainWindowCheckBox.Value = 0;
                    disp("Disabling couple to main window since just one data point is plotted there in grid activity view mode!");
                    return;
                end
                if strcmp(app.Mainapp.PlotAppearance.MainWindow.Data.Plottype, "AxonViewer")
                    app.CoupleTimetoMainWindowCheckBox.Value = 0;
                    disp("Disabling couple to main window since just one data point is plotted there in axon viewer view mode!")
                    return;
                end

                app.TimeWindowfromtoinsEditField.Enable = "off";
            else
                app.TimeWindowfromtoinsEditField.Enable = "on";
                if isempty(app.TimeWindowfromtoinsEditField.Value)

                    TimeRangePlot = str2double(app.Mainapp.TimeRangeViewBox.Value(1:end-1));
                    if contains(app.DataTypeDropDown.Value,"Preprocessed Data")
                        if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                            StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                        else
                            StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                        end
                    else
                        StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                    end

                    app.TimeWindowfromtoinsEditField.Value = strcat(num2str(StartTime),",",num2str(StartTime+TimeRangePlot));
                end
            end
            
            % freqs = str2double(strsplit(app.NarrowbandCutoffLowerHigherEditField.Value,','));
            % 
            % [app.NarrowbandFilterorderEditField.Value] = Utility_Determine_FIR_FilterOrder(app,freqs(1));

            app.ChangeMainPlot = 0; 
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1; 
        end

        % Value changed function: TimeWindowfromtoinsEditField
        function TimeWindowfromtoinsEditFieldValueChanged(app, event)
            value = app.TimeWindowfromtoinsEditField.Value;
            freqs = str2double(strsplit(app.NarrowbandCutoffLowerHigherEditField.Value,','));
            
            [app.NarrowbandFilterorderEditField.Value] = Utility_Determine_FIR_FilterOrder(app,freqs(1));

            app.ChangeMainPlot = 0; 
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1; 
        end

        % Menu selected function: InstantaneousPhasePlotMenu
        function InstantaneousPhasePlotMenuSelected(app, event)
            [file, path] = uiputfile('*.svg', 'Save as');
            
            exportgraphics(app.UIAxes, fullfile(path,file), 'ContentType', 'vector');
        end

        % Menu selected function: InstantaneousFrequencyPlotMenu
        function InstantaneousFrequencyPlotMenuSelected(app, event)
            [file, path] = uiputfile('*.svg', 'Save as');
            
            exportgraphics(app.UIAxes_2, fullfile(path,file), 'ContentType', 'vector');
        end

        % Menu selected function: PhaseSyncAllChannelPlotMenu
        function PhaseSyncAllChannelPlotMenuSelected(app, event)
            [file, path] = uiputfile('*.svg', 'Save as');
            
            exportgraphics(app.UIAxes_3, fullfile(path,file), 'ContentType', 'vector');
        end

        % Menu selected function: PhaseSyncPolarPlotMenu
        function PhaseSyncPolarPlotMenuSelected(app, event)
            [file, path] = uiputfile('*.svg', 'Save as');
            
            exportgraphics(app.Panel, fullfile(path,file), 'ContentType', 'vector');
        end

        % Menu selected function: InstantaneousPhasePlotMenu_2
        function InstantaneousPhasePlotMenu_2Selected(app, event)
            [file, path] = uiputfile('*.eps', 'Save as');

            exportgraphics(app.UIAxes, fullfile(path,file), 'ContentType', 'vector')
        end

        % Menu selected function: InstantaneousFrequencyPlotMenu_2
        function InstantaneousFrequencyPlotMenu_2Selected(app, event)
            [file, path] = uiputfile('*.eps', 'Save as');

            exportgraphics(app.UIAxes_2, fullfile(path,file), 'ContentType', 'vector')
        end

        % Menu selected function: PhaseSyncAllChannelPlotMenu_2
        function PhaseSyncAllChannelPlotMenu_2Selected(app, event)
            [file, path] = uiputfile('*.eps', 'Save as');

            exportgraphics(app.UIAxes_3, fullfile(path,file), 'ContentType', 'vector')
        end

        % Menu selected function: PhaseSyncPolarPlotMenu_2
        function PhaseSyncPolarPlotMenu_2Selected(app, event)
            [file, path] = uiputfile('*.eps', 'Save as');

            exportgraphics(app.Panel, fullfile(path,file), 'ContentType', 'vector')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InstantaneousFrequencyUIFigure = uifigure('Visible', 'off');
            app.InstantaneousFrequencyUIFigure.Name = 'Instantaneous Frequency';
            app.InstantaneousFrequencyUIFigure.Icon = 'Logo.png';
            app.InstantaneousFrequencyUIFigure.CloseRequestFcn = createCallbackFcn(app, @InstantaneousFrequencyUIFigureCloseRequest, true);
            app.SnapshotMenu = uimenu(app.InstantaneousFrequencyUIFigure);
            app.SnapshotMenu.Text = '   Snapshot   ';
            app.SaveSnapshotofWindowMenu = uimenu(app.SnapshotMenu);
            app.SaveSnapshotofWindowMenu.Text = 'Save Snapshot of Window';
            app.PNGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.PNGMenu.MenuSelectedFcn = createCallbackFcn(app, @PNGMenuSelected, true);
            app.PNGMenu.Text = 'PNG';
            app.JPGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.JPGMenu.MenuSelectedFcn = createCallbackFcn(app, @JPGMenuSelected, true);
            app.JPGMenu.Text = 'JPG';
            app.SVGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.SVGMenu.Text = 'SVG';
            app.InstantaneousPhasePlotMenu = uimenu(app.SVGMenu);
            app.InstantaneousPhasePlotMenu.MenuSelectedFcn = createCallbackFcn(app, @InstantaneousPhasePlotMenuSelected, true);
            app.InstantaneousPhasePlotMenu.Text = 'Instantaneous Phase Plot';
            app.InstantaneousFrequencyPlotMenu = uimenu(app.SVGMenu);
            app.InstantaneousFrequencyPlotMenu.MenuSelectedFcn = createCallbackFcn(app, @InstantaneousFrequencyPlotMenuSelected, true);
            app.InstantaneousFrequencyPlotMenu.Text = 'Instantaneous Frequency Plot';
            app.PhaseSyncAllChannelPlotMenu = uimenu(app.SVGMenu);
            app.PhaseSyncAllChannelPlotMenu.MenuSelectedFcn = createCallbackFcn(app, @PhaseSyncAllChannelPlotMenuSelected, true);
            app.PhaseSyncAllChannelPlotMenu.Text = 'Phase Sync All Channel Plot';
            app.PhaseSyncPolarPlotMenu = uimenu(app.SVGMenu);
            app.PhaseSyncPolarPlotMenu.MenuSelectedFcn = createCallbackFcn(app, @PhaseSyncPolarPlotMenuSelected, true);
            app.PhaseSyncPolarPlotMenu.Text = 'Phase Sync Polar Plot';
            app.EPSMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.EPSMenu.Text = 'EPS';
            app.InstantaneousPhasePlotMenu_2 = uimenu(app.EPSMenu);
            app.InstantaneousPhasePlotMenu_2.MenuSelectedFcn = createCallbackFcn(app, @InstantaneousPhasePlotMenu_2Selected, true);
            app.InstantaneousPhasePlotMenu_2.Text = 'Instantaneous Phase Plot';
            app.InstantaneousFrequencyPlotMenu_2 = uimenu(app.EPSMenu);
            app.InstantaneousFrequencyPlotMenu_2.MenuSelectedFcn = createCallbackFcn(app, @InstantaneousFrequencyPlotMenu_2Selected, true);
            app.InstantaneousFrequencyPlotMenu_2.Text = 'Instantaneous Frequency Plot';
            app.PhaseSyncAllChannelPlotMenu_2 = uimenu(app.EPSMenu);
            app.PhaseSyncAllChannelPlotMenu_2.MenuSelectedFcn = createCallbackFcn(app, @PhaseSyncAllChannelPlotMenu_2Selected, true);
            app.PhaseSyncAllChannelPlotMenu_2.Text = 'Phase Sync All Channel Plot';
            app.PhaseSyncPolarPlotMenu_2 = uimenu(app.EPSMenu);
            app.PhaseSyncPolarPlotMenu_2.MenuSelectedFcn = createCallbackFcn(app, @PhaseSyncPolarPlotMenu_2Selected, true);
            app.PhaseSyncPolarPlotMenu_2.Text = 'Phase Sync Polar Plot';
            app.PlotMenu = uimenu(app.InstantaneousFrequencyUIFigure);
            app.PlotMenu.Text = '   Plot   ';
            app.ChangePlotPropertiesMenu = uimenu(app.PlotMenu);
            app.ChangePlotPropertiesMenu.Text = 'Change Plot Properties';
            app.AlltoAllSyncPlotMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.AlltoAllSyncPlotMenu.Text = 'All-to-All Sync Plot';
            app.FontSizeMenu = uimenu(app.AlltoAllSyncPlotMenu);
            app.FontSizeMenu.MenuSelectedFcn = createCallbackFcn(app, @FontSizeMenuSelected, true);
            app.FontSizeMenu.Text = 'FontSize';
            app.XLabelMenu = uimenu(app.AlltoAllSyncPlotMenu);
            app.XLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @XLabelMenuSelected, true);
            app.XLabelMenu.Text = 'XLabel';
            app.YLabelMenu = uimenu(app.AlltoAllSyncPlotMenu);
            app.YLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @YLabelMenuSelected, true);
            app.YLabelMenu.Text = 'YLabel';
            app.CLabelMenu = uimenu(app.AlltoAllSyncPlotMenu);
            app.CLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @CLabelMenuSelected, true);
            app.CLabelMenu.Text = 'CLabel';
            app.TitleMenu = uimenu(app.AlltoAllSyncPlotMenu);
            app.TitleMenu.MenuSelectedFcn = createCallbackFcn(app, @TitleMenuSelected, true);
            app.TitleMenu.Text = 'Title';
            app.RESETMenu = uimenu(app.AlltoAllSyncPlotMenu);
            app.RESETMenu.MenuSelectedFcn = createCallbackFcn(app, @RESETMenuSelected, true);
            app.RESETMenu.Text = 'RESET';
            app.PhaseAngleDifferencePlotMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.PhaseAngleDifferencePlotMenu.Text = 'Phase Angle Difference Plot';
            app.FontSizeMenu_2 = uimenu(app.PhaseAngleDifferencePlotMenu);
            app.FontSizeMenu_2.MenuSelectedFcn = createCallbackFcn(app, @FontSizeMenu_2Selected, true);
            app.FontSizeMenu_2.Text = 'FontSize';
            app.ColorPhaseAngleDifferencesMenu = uimenu(app.PhaseAngleDifferencePlotMenu);
            app.ColorPhaseAngleDifferencesMenu.MenuSelectedFcn = createCallbackFcn(app, @ColorPhaseAngleDifferencesMenuSelected, true);
            app.ColorPhaseAngleDifferencesMenu.Text = 'Color Phase Angle Differences';
            app.ColorAveragePhaseVectorMenu = uimenu(app.PhaseAngleDifferencePlotMenu);
            app.ColorAveragePhaseVectorMenu.MenuSelectedFcn = createCallbackFcn(app, @ColorAveragePhaseVectorMenuSelected, true);
            app.ColorAveragePhaseVectorMenu.Text = 'Color Average Phase Vector ';
            app.LineWidthPhaseAngleDifferencesMenu = uimenu(app.PhaseAngleDifferencePlotMenu);
            app.LineWidthPhaseAngleDifferencesMenu.MenuSelectedFcn = createCallbackFcn(app, @LineWidthPhaseAngleDifferencesMenuSelected, true);
            app.LineWidthPhaseAngleDifferencesMenu.Text = 'LineWidth Phase Angle Differences';
            app.LineWidthAveragePhaseVectorMenu = uimenu(app.PhaseAngleDifferencePlotMenu);
            app.LineWidthAveragePhaseVectorMenu.MenuSelectedFcn = createCallbackFcn(app, @LineWidthAveragePhaseVectorMenuSelected, true);
            app.LineWidthAveragePhaseVectorMenu.Text = 'LineWidth Average Phase Vector ';
            app.BackgorundColorMenu = uimenu(app.PhaseAngleDifferencePlotMenu);
            app.BackgorundColorMenu.MenuSelectedFcn = createCallbackFcn(app, @BackgorundColorMenuSelected, true);
            app.BackgorundColorMenu.Text = 'Backgorund Color';
            app.TitleMenu_2 = uimenu(app.PhaseAngleDifferencePlotMenu);
            app.TitleMenu_2.MenuSelectedFcn = createCallbackFcn(app, @TitleMenu_2Selected, true);
            app.TitleMenu_2.Text = 'Title';
            app.RESETMenu_2 = uimenu(app.PhaseAngleDifferencePlotMenu);
            app.RESETMenu_2.MenuSelectedFcn = createCallbackFcn(app, @RESETMenu_2Selected, true);
            app.RESETMenu_2.Text = 'RESET';
            app.PhaseAnglesPlotMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.PhaseAnglesPlotMenu.Text = 'Phase Angles Plot';
            app.FontSizeMenu_3 = uimenu(app.PhaseAnglesPlotMenu);
            app.FontSizeMenu_3.MenuSelectedFcn = createCallbackFcn(app, @FontSizeMenu_3Selected, true);
            app.FontSizeMenu_3.Text = 'FontSize';
            app.ColormapMenu = uimenu(app.PhaseAnglesPlotMenu);
            app.ColormapMenu.MenuSelectedFcn = createCallbackFcn(app, @ColormapMenuSelected, true);
            app.ColormapMenu.Text = 'Colormap';
            app.LineWidthPhaseAnglesMenu = uimenu(app.PhaseAnglesPlotMenu);
            app.LineWidthPhaseAnglesMenu.MenuSelectedFcn = createCallbackFcn(app, @LineWidthPhaseAnglesMenuSelected, true);
            app.LineWidthPhaseAnglesMenu.Text = 'LineWidth Phase Angles';
            app.BackgorundColorMenu_2 = uimenu(app.PhaseAnglesPlotMenu);
            app.BackgorundColorMenu_2.MenuSelectedFcn = createCallbackFcn(app, @BackgorundColorMenu_2Selected, true);
            app.BackgorundColorMenu_2.Text = 'Backgorund Color';
            app.XLabelMenu_2 = uimenu(app.PhaseAnglesPlotMenu);
            app.XLabelMenu_2.MenuSelectedFcn = createCallbackFcn(app, @XLabelMenu_2Selected, true);
            app.XLabelMenu_2.Text = 'XLabel';
            app.YLabelMenu_2 = uimenu(app.PhaseAnglesPlotMenu);
            app.YLabelMenu_2.MenuSelectedFcn = createCallbackFcn(app, @YLabelMenu_2Selected, true);
            app.YLabelMenu_2.Text = 'YLabel';
            app.TitleMenu_3 = uimenu(app.PhaseAnglesPlotMenu);
            app.TitleMenu_3.MenuSelectedFcn = createCallbackFcn(app, @TitleMenu_3Selected, true);
            app.TitleMenu_3.Text = 'Title';
            app.RESETMenu_3 = uimenu(app.PhaseAnglesPlotMenu);
            app.RESETMenu_3.MenuSelectedFcn = createCallbackFcn(app, @RESETMenu_3Selected, true);
            app.RESETMenu_3.Text = 'RESET';
            app.PhaseEnvelopePlotMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.PhaseEnvelopePlotMenu.Text = 'Phase Envelope Plot';
            app.FontSizeMenu_4 = uimenu(app.PhaseEnvelopePlotMenu);
            app.FontSizeMenu_4.MenuSelectedFcn = createCallbackFcn(app, @FontSizeMenu_4Selected, true);
            app.FontSizeMenu_4.Text = 'FontSize';
            app.ColormapMenu_2 = uimenu(app.PhaseEnvelopePlotMenu);
            app.ColormapMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ColormapMenu_2Selected, true);
            app.ColormapMenu_2.Text = 'Colormap';
            app.LineWidthPhaseAngleEnvelopesMenu = uimenu(app.PhaseEnvelopePlotMenu);
            app.LineWidthPhaseAngleEnvelopesMenu.MenuSelectedFcn = createCallbackFcn(app, @LineWidthPhaseAngleEnvelopesMenuSelected, true);
            app.LineWidthPhaseAngleEnvelopesMenu.Text = 'LineWidth Phase Angle Envelopes';
            app.BackgorundColorMenu_3 = uimenu(app.PhaseEnvelopePlotMenu);
            app.BackgorundColorMenu_3.MenuSelectedFcn = createCallbackFcn(app, @BackgorundColorMenu_3Selected, true);
            app.BackgorundColorMenu_3.Text = 'Backgorund Color';
            app.XLabelMenu_3 = uimenu(app.PhaseEnvelopePlotMenu);
            app.XLabelMenu_3.MenuSelectedFcn = createCallbackFcn(app, @XLabelMenu_3Selected, true);
            app.XLabelMenu_3.Text = 'XLabel';
            app.YLabelMenu_3 = uimenu(app.PhaseEnvelopePlotMenu);
            app.YLabelMenu_3.MenuSelectedFcn = createCallbackFcn(app, @YLabelMenu_3Selected, true);
            app.YLabelMenu_3.Text = 'YLabel';
            app.TitleMenu_4 = uimenu(app.PhaseEnvelopePlotMenu);
            app.TitleMenu_4.MenuSelectedFcn = createCallbackFcn(app, @TitleMenu_4Selected, true);
            app.TitleMenu_4.Text = 'Title';
            app.RESETMenu_4 = uimenu(app.PhaseEnvelopePlotMenu);
            app.RESETMenu_4.MenuSelectedFcn = createCallbackFcn(app, @RESETMenu_4Selected, true);
            app.RESETMenu_4.Text = 'RESET';
            app.SaveCustomeAppearanceMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.SaveCustomeAppearanceMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveCustomeAppearanceMenuSelected, true);
            app.SaveCustomeAppearanceMenu.Text = 'Save Custome Appearance';
            app.DeleteCustomeAppearanceMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.DeleteCustomeAppearanceMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteCustomeAppearanceMenuSelected, true);
            app.DeleteCustomeAppearanceMenu.Text = 'Delete Custome Appearance';
            app.ExportDataMenu = uimenu(app.InstantaneousFrequencyUIFigure);
            app.ExportDataMenu.Text = '   Export Data   ';
            app.PhaseAngleTimeSeriesMenu = uimenu(app.ExportDataMenu);
            app.PhaseAngleTimeSeriesMenu.Text = 'Phase Angle Time Series';
            app.matMenu_2 = uimenu(app.PhaseAngleTimeSeriesMenu);
            app.matMenu_2.MenuSelectedFcn = createCallbackFcn(app, @matMenu_2Selected, true);
            app.matMenu_2.Text = '.mat';
            app.txtMenu_2 = uimenu(app.PhaseAngleTimeSeriesMenu);
            app.txtMenu_2.MenuSelectedFcn = createCallbackFcn(app, @txtMenu_2Selected, true);
            app.txtMenu_2.Text = '.txt';
            app.PhaseAngleAmplitudeEnvelopeMenu = uimenu(app.ExportDataMenu);
            app.PhaseAngleAmplitudeEnvelopeMenu.Text = 'Phase Angle Amplitude Envelope';
            app.matMenu_3 = uimenu(app.PhaseAngleAmplitudeEnvelopeMenu);
            app.matMenu_3.MenuSelectedFcn = createCallbackFcn(app, @matMenu_3Selected, true);
            app.matMenu_3.Text = '.mat';
            app.txtMenu_3 = uimenu(app.PhaseAngleAmplitudeEnvelopeMenu);
            app.txtMenu_3.MenuSelectedFcn = createCallbackFcn(app, @txtMenu_3Selected, true);
            app.txtMenu_3.Text = '.txt';
            app.AllToAllSynchronizationMenu = uimenu(app.ExportDataMenu);
            app.AllToAllSynchronizationMenu.Text = 'All To All Synchronization';
            app.matMenu_4 = uimenu(app.AllToAllSynchronizationMenu);
            app.matMenu_4.MenuSelectedFcn = createCallbackFcn(app, @matMenu_4Selected, true);
            app.matMenu_4.Text = '.mat';
            app.txtMenu_4 = uimenu(app.AllToAllSynchronizationMenu);
            app.txtMenu_4.MenuSelectedFcn = createCallbackFcn(app, @txtMenu_4Selected, true);
            app.txtMenu_4.Text = '.txt';
            app.PolarPhaseAngleDifferencesMenu = uimenu(app.ExportDataMenu);
            app.PolarPhaseAngleDifferencesMenu.Text = 'Polar Phase Angle Differences';
            app.matMenu_5 = uimenu(app.PolarPhaseAngleDifferencesMenu);
            app.matMenu_5.MenuSelectedFcn = createCallbackFcn(app, @matMenu_5Selected, true);
            app.matMenu_5.Text = '.mat';
            app.txtMenu_5 = uimenu(app.PolarPhaseAngleDifferencesMenu);
            app.txtMenu_5.MenuSelectedFcn = createCallbackFcn(app, @txtMenu_5Selected, true);
            app.txtMenu_5.Text = '.txt';
            app.ExtrasMenu = uimenu(app.InstantaneousFrequencyUIFigure);
            app.ExtrasMenu.Text = '   Extras   ';
            app.ChangeLowPassParameterMenu = uimenu(app.ExtrasMenu);
            app.ChangeLowPassParameterMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeLowPassParameterMenuSelected, true);
            app.ChangeLowPassParameterMenu.Text = 'Change Low-Pass Parameter';
            app.ShowDataAnalysedMenu = uimenu(app.ExtrasMenu);
            app.ShowDataAnalysedMenu.Text = 'Show Data Analysed';
            app.ONMenu = uimenu(app.ShowDataAnalysedMenu);
            app.ONMenu.MenuSelectedFcn = createCallbackFcn(app, @ONMenuSelected, true);
            app.ONMenu.Text = 'ON';
            app.OFFMenu = uimenu(app.ShowDataAnalysedMenu);
            app.OFFMenu.MenuSelectedFcn = createCallbackFcn(app, @OFFMenuSelected, true);
            app.OFFMenu.Text = 'OFF';
            app.ChangeFilterTypeMenu = uimenu(app.ExtrasMenu);
            app.ChangeFilterTypeMenu.Text = 'Change Filter Type';
            app.ButterworthMenu = uimenu(app.ChangeFilterTypeMenu);
            app.ButterworthMenu.MenuSelectedFcn = createCallbackFcn(app, @ButterworthMenuSelected, true);
            app.ButterworthMenu.Text = 'Butterworth';
            app.FIRMenu = uimenu(app.ChangeFilterTypeMenu);
            app.FIRMenu.MenuSelectedFcn = createCallbackFcn(app, @FIRMenuSelected, true);
            app.FIRMenu.Text = 'FIR';
            app.UIAxes = uiaxes(app.InstantaneousFrequencyUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.InstantaneousFrequencyUIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.InstantaneousFrequencyUIFigure);
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.SettingsPanel = uipanel(app.InstantaneousFrequencyUIFigure);
            app.SettingsPanel.Title = 'Settings';
            app.NarrowbandCutoffLowerHigherEditFieldLabel = uilabel(app.SettingsPanel);
            app.NarrowbandCutoffLowerHigherEditFieldLabel.Text = {'Narrowband Cutoff'; '[Lower,Higher]'};
            app.NarrowbandCutoffLowerHigherEditField = uieditfield(app.SettingsPanel, 'text');
            app.NarrowbandCutoffLowerHigherEditField.ValueChangedFcn = createCallbackFcn(app, @NarrowbandCutoffLowerHigherEditFieldValueChanged, true);
            app.NarrowbandCutoffLowerHigherEditField.Value = '12,16';
            app.NarrowbandFilterorderLabel = uilabel(app.SettingsPanel);
            app.NarrowbandFilterorderLabel.Text = {'Narrowband'; 'Filterorder'};
            app.NarrowbandFilterorderEditField = uieditfield(app.SettingsPanel, 'text');
            app.NarrowbandFilterorderEditField.ValueChangedFcn = createCallbackFcn(app, @NarrowbandFilterorderEditFieldValueChanged, true);
            app.NarrowbandFilterorderEditField.Value = '4';
            app.LockYlimCheckBox = uicheckbox(app.SettingsPanel);
            app.LockYlimCheckBox.Text = '   Lock Ylim';
            app.LockYlimCheckBox.Value = true;
            app.CalculationMethodDropDownLabel = uilabel(app.SettingsPanel);
            app.CalculationMethodDropDownLabel.Text = 'Calculation Method';
            app.CalculationMethodDropDown = uidropdown(app.SettingsPanel);
            app.CalculationMethodDropDown.Items = {'Hilbert Transform', 'Endpoint Corrected Hilbert Transform', 'Wavelet Convolution'};
            app.CalculationMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @CalculationMethodDropDownValueChanged, true);
            app.CalculationMethodDropDown.Value = 'Hilbert Transform';
            app.DataTypeDropDownLabel = uilabel(app.SettingsPanel);
            app.DataTypeDropDownLabel.Text = 'Data Type';
            app.DataTypeDropDown = uidropdown(app.SettingsPanel);
            app.DataTypeDropDown.Items = {'Raw Data'};
            app.DataTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DataTypeDropDownValueChanged, true);
            app.DataTypeDropDown.Value = 'Raw Data';
            app.ForceFilterOFFCheckBox = uicheckbox(app.SettingsPanel);
            app.ForceFilterOFFCheckBox.ValueChangedFcn = createCallbackFcn(app, @ForceFilterOFFCheckBoxValueChanged, true);
            app.ForceFilterOFFCheckBox.Text = '   Force Filter OFF';
            app.ECHTFilterorderEditField = uieditfield(app.SettingsPanel, 'text');
            app.ECHTFilterorderEditField.ValueChangedFcn = createCallbackFcn(app, @ECHTFilterorderEditFieldValueChanged, true);
            app.ECHTFilterorderEditField.Value = '2';
            app.ECHTFilterorderLabel = uilabel(app.SettingsPanel);
            app.ECHTFilterorderLabel.Text = {'ECHT'; 'Filterorder'};
            app.CoupleTimetoMainWindowCheckBox = uicheckbox(app.SettingsPanel);
            app.CoupleTimetoMainWindowCheckBox.ValueChangedFcn = createCallbackFcn(app, @CoupleTimetoMainWindowCheckBoxValueChanged, true);
            app.CoupleTimetoMainWindowCheckBox.Text = {'  Couple Time'; '  to Main Window'};
            app.CoupleTimetoMainWindowCheckBox.Value = true;
            app.TimeWindowfromtoinsEditFieldLabel = uilabel(app.SettingsPanel);
            app.TimeWindowfromtoinsEditFieldLabel.Text = {'Analysis Time'; 'Range '; '[from, to] in s'};
            app.TimeWindowfromtoinsEditField = uieditfield(app.SettingsPanel, 'text');
            app.TimeWindowfromtoinsEditField.ValueChangedFcn = createCallbackFcn(app, @TimeWindowfromtoinsEditFieldValueChanged, true);
            app.TimeWindowfromtoinsEditField.Enable = 'off';
            app.CloseButton = uibutton(app.InstantaneousFrequencyUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.SettingsLabel = uilabel(app.InstantaneousFrequencyUIFigure);
            app.SettingsLabel.Text = 'Settings';
            app.Panel = uipanel(app.InstantaneousFrequencyUIFigure);
            app.ChannelSelectionDropDownLabel = uilabel(app.InstantaneousFrequencyUIFigure);
            app.ChannelSelectionDropDownLabel.Text = 'Channel Selection';
            app.ChannelSelectionDropDown = uidropdown(app.InstantaneousFrequencyUIFigure);
            app.ChannelSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelSelectionDropDownValueChanged, true);
            app.ChannelSelectionDropDown_2 = uidropdown(app.InstantaneousFrequencyUIFigure);
            app.ChannelSelectionDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ChannelSelectionDropDown_2ValueChanged, true);
            app.vsLabel = uilabel(app.InstantaneousFrequencyUIFigure);
            app.vsLabel.Text = 'vs';
            app.TextArea = uitextarea(app.InstantaneousFrequencyUIFigure);
            % Show the figure after all components are created
            app.InstantaneousFrequencyUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Live_InstFrequency_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.InstantaneousFrequencyUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.InstantaneousFrequencyUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InstantaneousFrequencyUIFigure)
        end
    end
end