classdef Spectral_Power_Estimate_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PowerEstimateWindowUIFigure     matlab.ui.Figure
        SnapshotMenu                    matlab.ui.container.Menu
        SaveSnapshotofWindowMenu        matlab.ui.container.Menu
        PNGMenu                         matlab.ui.container.Menu
        JPGMenu                         matlab.ui.container.Menu
        SVGMenu                         matlab.ui.container.Menu
        EPSMenu                         matlab.ui.container.Menu
        PlotMenu                        matlab.ui.container.Menu
        ChangePlotPropertiesMenu        matlab.ui.container.Menu
        PowerEstimatePlotMenu           matlab.ui.container.Menu
        FontSizeMenu                    matlab.ui.container.Menu
        BarColorMenu                    matlab.ui.container.Menu
        BackgorundColorMenu             matlab.ui.container.Menu
        XLabelMenu                      matlab.ui.container.Menu
        YLabelMenu                      matlab.ui.container.Menu
        RESETMenu                       matlab.ui.container.Menu
        SaveCustomeAppearanceMenu       matlab.ui.container.Menu
        DeleteCustomeAppearanceMenu     matlab.ui.container.Menu
        ExportDataMenu                  matlab.ui.container.Menu
        matMenu                         matlab.ui.container.Menu
        txtMenu                         matlab.ui.container.Menu
        xlsxMenu                        matlab.ui.container.Menu
        Button                          matlab.ui.control.Button
        ChannelSelectionPreprocessedChannelEditField  matlab.ui.control.EditField
        ChannelSelectionEditFieldLabel  matlab.ui.control.Label
        CloseButton                     matlab.ui.control.Button
        PowerEstimateOptionsPanel       matlab.ui.container.Panel
        CoupleTimetoMainWindowCheckBox  matlab.ui.control.CheckBox
        TimeWindowfromtoinsEditField    matlab.ui.control.EditField
        AnalysisTimeRangefromtoinsEditFieldLabel  matlab.ui.control.Label
        PowerEstimateOptionsLabel       matlab.ui.control.Label
        DataTypeDropDown                matlab.ui.control.DropDown
        DataTypeDropDownLabel           matlab.ui.control.Label
        LockYLimCheckBox                matlab.ui.control.CheckBox
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Mainapp = []; % Description
        ExistflagSDE = [];
        PDLim = [];
        Startup = 1;
        ChangeMainPlot = 1;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            movegui(app.PowerEstimateWindowUIFigure, 'north');
            
            app.Mainapp = Mainapp;
            app.Mainapp.SpectralEstApp = app;
            
            app = Utility_Change_Light_Dark_Mode(app,"LiveSpectralPowerWindow");

            if isfield(app.Mainapp.Data,'Preprocessed')
                app.DataTypeDropDown.Items{2} = 'Preprocessed Data';
                app.DataTypeDropDown.Items{1} = 'Raw Data';
            else
                app.DataTypeDropDown.Items{1} = 'Raw Data';
            end

            app.ExistflagSDE = 1;

            if strcmp(app.Mainapp.PlotAppearance.MainWindow.Data.Plottype, "Surf") || strcmp(app.Mainapp.PlotAppearance.MainWindow.Data.Plottype,"Mesh")
                app.CoupleTimetoMainWindowCheckBox.Value = 0;
                disp("Disabling couple to main window since just one data point is plotted there in grid activity view mode!");
                app.TimeWindowfromtoinsEditField.Enable = "on";
                if isempty(app.TimeWindowfromtoinsEditField.Value)
                    
                    TimeRangePlot = 1.5;
                    if strcmp(app.DataTypeDropDown.Value,"Preprocessed Data")
                        if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                            StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                        else
                            StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                        end
                    else
                        StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                    end
                    
                    if StartTime<app.Mainapp.Data.Time(end) || StartTime+TimeRangePlot<app.Mainapp.Data.Time(end)
                        app.TimeWindowfromtoinsEditField.Value = strcat(num2str(StartTime),",",num2str(StartTime+TimeRangePlot));
                    else
                        StartTime = app.Mainapp.Data.Time(end)-1;
                        TimeRangePlot = 0.9;
                        app.TimeWindowfromtoinsEditField.Value = strcat(num2str(StartTime),",",num2str(StartTime+TimeRangePlot));
                    end
                end
            end
            if strcmp(app.Mainapp.PlotAppearance.MainWindow.Data.Plottype, "AxonViewer")
                app.CoupleTimetoMainWindowCheckBox.Value = 0;
                disp("Disabling couple to main window since just one data point is plotted there in axon viewer view mode!")
                app.TimeWindowfromtoinsEditField.Enable = "on";
                if isempty(app.TimeWindowfromtoinsEditField.Value)

                    TimeRangePlot = 1.5;
                    if strcmp(app.DataTypeDropDown.Value,"Preprocessed Data")
                        if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                            StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                        else
                            StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                        end
                    else
                        StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                    end

                    if StartTime<app.Mainapp.Data.Time(end) || StartTime+TimeRangePlot<app.Mainapp.Data.Time(end)
                        app.TimeWindowfromtoinsEditField.Value = strcat(num2str(StartTime),",",num2str(StartTime+TimeRangePlot));
                    else
                        StartTime = app.Mainapp.Data.Time(end)-1;
                        TimeRangePlot = 0.9;
                        app.TimeWindowfromtoinsEditField.Value = strcat(num2str(StartTime),",",num2str(StartTime+TimeRangePlot));
                    end
                end
            end

           % app.ChannelSelectionPreprocessedChannelEditField.Value = strcat(num2str(1),",",num2str(size(app.Mainapp.Data.Raw,1)));

            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot

            app.Mainapp.SpectralEstApp = app;
            app.ChangeMainPlot = 0;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1;
            app.Startup = 0;
        end

        % Close request function: PowerEstimateWindowUIFigure
        function PowerEstimateWindowUIFigureCloseRequest(app, event)
            try
                app.Mainapp.SpectralEstApp = [];
                app.ChangeMainPlot = 1;
                if ~isempty(app.Mainapp.ProbeViewWindowHandle) % delete option from probe view 
                    for i = 1:length(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                        if strcmp(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Main Plot Power Estimate')
                            app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items(i) = [];
                            break;
                        end
                    end
                else % if no probe view available
     
                end
    
                app.ExistflagSDE = [];
            end
            delete(app)
        end

        % Callback function
        function CloseButtonPushed(app, event)
            if ~isempty(app.Mainapp.ProbeViewWindowHandle) % delete option from probe view 
                for i = 1:length(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                    if strcmp(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Main Plot Power Estimate')
                        app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items(i) = [];
                        break;
                    end
                end
            else % if no probe view available
 
            end

            app.ExistflagSDE = [];
            delete(app);
        end

        % Callback function
        function ChannelSelectionPreprocessedChannelEditFieldValueChanged(app, event)
            
            if ~isempty(app.ChannelSelectionPreprocessedChannelEditField.Value)
                [app.ChannelSelectionPreprocessedChannelEditField.Value] = Utility_SimpleCheckInputs(app.ChannelSelectionPreprocessedChannelEditField.Value,"Two",strcat('1,',num2str(size(app.Mainapp.Data.Raw,1))),1,0);
            end

            if ~isempty(app.ChannelSelectionPreprocessedChannelEditField.Value)
                app.Mainapp.ChannelChange = "EditField";
    
                %% Show mV scale if only one channel shown
                commaindicie = find(app.ChannelSelectionPreprocessedChannelEditField.Value==',');
                SelectedChannel(1) = str2double(app.ChannelSelectionPreprocessedChannelEditField.Value(1:commaindicie(1)-1));
                SelectedChannel(2) = str2double(app.ChannelSelectionPreprocessedChannelEditField.Value(commaindicie(1)+1:end));
    
                app.Mainapp.ActiveChannel = app.Mainapp.Data.Info.ProbeInfo.ActiveChannel(SelectedChannel(1):SelectedChannel(2));
    
                app.Mainapp.SpectralEstApp = app;
                app.ChangeMainPlot = 0;
                Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
                app.ChangeMainPlot = 1;
            end
        end

        % Value changed function: LockYLimCheckBox
        function LockYLimCheckBoxValueChanged(app, event)
            value = app.LockYLimCheckBox.Value;
            
        end

        % Menu selected function: PNGMenu
        function PNGMenuSelected(app, event)
            [file, path] = uiputfile('*.png', 'Save as');

            exportapp(app.PowerEstimateWindowUIFigure,fullfile(path,file)) 
        end

        % Menu selected function: JPGMenu
        function JPGMenuSelected(app, event)
            [file, path] = uiputfile('*.jpg', 'Save as');

            exportapp(app.PowerEstimateWindowUIFigure,fullfile(path,file)) 
        end

        % Menu selected function: matMenu
        function matMenuSelected(app, event)
            

            TimeRangePlot = str2double(app.Mainapp.TimeRangeViewBox.Value(1:end-1));
            if strcmp(app.DataTypeDropDown.Value,"Preprocessed Data")
                if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                else
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                end
            else
                StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
            end

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Live_Spectral_Power_Estimate_Main_Window",0);
        end

        % Menu selected function: txtMenu
        function txtMenuSelected(app, event)
            TimeRangePlot = str2double(app.Mainapp.TimeRangeViewBox.Value(1:end-1));
            if strcmp(app.DataTypeDropDown.Value,"Preprocessed Data")
                if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                else
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                end
            else
                StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
            end
            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Live_Spectral_Power_Estimate_Main_Window",0);
        end

        % Menu selected function: FontSizeMenu
        function FontSizeMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'fontsize',app.Mainapp,"LivePEWindowDataFontSize");
            waitfor(h);

            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.LivePowerEstimateWindow.FontSize;
        end

        % Menu selected function: BarColorMenu
        function BarColorMenuSelected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.Mainapp.PlotAppearance.LivePowerEstimateWindow.BarColor = selectedColor;
            end

            app.Mainapp.SpectralEstApp = app;
            app.ChangeMainPlot = 0;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1;
        end

        % Menu selected function: BackgorundColorMenu
        function BackgorundColorMenuSelected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.Mainapp.PlotAppearance.LivePowerEstimateWindow.BackgroundColor = selectedColor;
                app.UIAxes.Color = app.Mainapp.PlotAppearance.LivePowerEstimateWindow.BackgroundColor;
            end
        end

        % Menu selected function: XLabelMenu
        function XLabelMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'xlabel',app.Mainapp,"LivePEWindowXLabel");
            waitfor(h);

            app.Mainapp.SpectralEstApp = app;
            app.ChangeMainPlot = 0;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1;
        end

        % Menu selected function: YLabelMenu
        function YLabelMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'ylabel',app.Mainapp,"LivePEWindowYLabel");
            waitfor(h);

            app.Mainapp.SpectralEstApp = app;
            app.ChangeMainPlot = 0;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1;
        end

        % Menu selected function: RESETMenu
        function RESETMenuSelected(app, event)
            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";

            [app.Mainapp.PlotAppearance] = Organize_Set_Standard_PlotAppearance("PowerEstimatePlot",app.Mainapp.PlotAppearance);

            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.LivePowerEstimateWindow.FontSize;
            app.UIAxes.Color = app.Mainapp.PlotAppearance.LivePowerEstimateWindow.BackgroundColor;

            app.Mainapp.SpectralEstApp = app;
            app.ChangeMainPlot = 0;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1;

        end

        % Value changed function: DataTypeDropDown
        function DataTypeDropDownValueChanged(app, event)
            
            app.Mainapp.SpectralEstApp = app;
            app.ChangeMainPlot = 0;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1;
        end

        % Menu selected function: SaveCustomeAppearanceMenu
        function SaveCustomeAppearanceMenuSelected(app, event)
            app.Mainapp.PlotAppearance = Utility_Save_Load_Delete_Plot_Appearance(app.Mainapp.PlotAppearance,app.Mainapp.executableFolder,"Save");
        end

        % Menu selected function: DeleteCustomeAppearanceMenu
        function DeleteCustomeAppearanceMenuSelected(app, event)
            app.Mainapp.PlotAppearance = Utility_Save_Load_Delete_Plot_Appearance(app.Mainapp.PlotAppearance,app.Mainapp.executableFolder,"Delete");
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed2(app, event)
            try
                app.Mainapp.TwoORThreeD = "TwoD";  
                app.Mainapp.SpectralEstApp = [];
                app.ChangeMainPlot = 1;
                
                if ~isempty(app.Mainapp.ProbeViewWindowHandle) % delete option from probe view 
                    for i = 1:length(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                        if strcmp(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Main Plot Power Estimate')
                            app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items(i) = [];
                            break;
                        end
                    end
                else % if no probe view available
     
                end
            end
            
            delete(app)
        end

        % Menu selected function: SVGMenu
        function SVGMenuSelected(app, event)
            [file, path] = uiputfile('*.svg', 'Save as');
            
            exportgraphics(app.PowerEstimateWindowUIFigure, fullfile(path,file), 'ContentType', 'vector');
        end

        % Menu selected function: xlsxMenu
        function xlsxMenuSelected(app, event)
            TimeRangePlot = str2double(app.Mainapp.TimeRangeViewBox.Value(1:end-1));
            if strcmp(app.DataTypeDropDown.Value,"Preprocessed Data")
                if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                else
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                end
            else
                StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
            end
            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".xlsx",app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Live_Spectral_Power_Estimate_Main_Window",0);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            Utility_Open_UIAxes_as_Figure(app.Mainapp.Data,app.UIAxes,"LivePowerEstimate");
        end

        % Value changed function: CoupleTimetoMainWindowCheckBox
        function CoupleTimetoMainWindowCheckBoxValueChanged(app, event)
            value = app.CoupleTimetoMainWindowCheckBox.Value;
            if value
                if strcmp(app.Mainapp.PlotAppearance.MainWindow.Data.Plottype, "Surf") || strcmp(app.Mainapp.PlotAppearance.MainWindow.Data.Plottype,"Mesh")
                    app.CoupleTimetoMainWindowCheckBox.Value = 0;
                    disp("Disabling couple to main window since just one data point is plotted there in grid activity view mode!");
                    return;
                end
                if strcmp(app.Mainapp.PlotAppearance.MainWindow.Data.Plottype, "AxonViewer")
                    app.CoupleTimetoMainWindowCheckBox.Value = 0;
                    disp("Disabling couple to main window since just one data point is plotted there in axon viewer view mode!")
                    return;
                end

                app.TimeWindowfromtoinsEditField.Enable = "off";
            else
                app.TimeWindowfromtoinsEditField.Enable = "on";
                if isempty(app.TimeWindowfromtoinsEditField.Value)

                    TimeRangePlot = str2double(app.Mainapp.TimeRangeViewBox.Value(1:end-1));
                    if strcmp(app.DataTypeDropDown.Value,"Preprocessed Data")
                        if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                            StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                        else
                            StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                        end
                    else
                        StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                    end

                    app.TimeWindowfromtoinsEditField.Value = strcat(num2str(StartTime),",",num2str(StartTime+TimeRangePlot));
                end
            end
            app.Mainapp.SpectralEstApp = app;
            app.ChangeMainPlot = 0;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1;
        end

        % Value changed function: TimeWindowfromtoinsEditField
        function TimeWindowfromtoinsEditFieldValueChanged(app, event)
            app.Mainapp.SpectralEstApp = app;
            app.ChangeMainPlot = 0;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1;
        end

        % Menu selected function: EPSMenu
        function EPSMenuSelected(app, event)
            [file, path] = uiputfile('*.eps', 'Save as');

            exportgraphics(app.UIAxes, fullfile(path,file), 'ContentType', 'vector')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PowerEstimateWindowUIFigure = uifigure('Visible', 'off');
            app.PowerEstimateWindowUIFigure.Name = 'Live Power Estimate Window';
            app.PowerEstimateWindowUIFigure.Icon = 'Logo.png';
            app.PowerEstimateWindowUIFigure.CloseRequestFcn = createCallbackFcn(app, @PowerEstimateWindowUIFigureCloseRequest, true);
            app.SnapshotMenu = uimenu(app.PowerEstimateWindowUIFigure);
            app.SnapshotMenu.Text = '   Snapshot   ';
            app.SaveSnapshotofWindowMenu = uimenu(app.SnapshotMenu);
            app.SaveSnapshotofWindowMenu.Text = 'Save Snapshot of Window';
            app.PNGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.PNGMenu.MenuSelectedFcn = createCallbackFcn(app, @PNGMenuSelected, true);
            app.PNGMenu.Text = 'PNG';
            app.JPGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.JPGMenu.MenuSelectedFcn = createCallbackFcn(app, @JPGMenuSelected, true);
            app.JPGMenu.Text = 'JPG';
            app.SVGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.SVGMenu.MenuSelectedFcn = createCallbackFcn(app, @SVGMenuSelected, true);
            app.SVGMenu.Text = 'SVG';
            app.EPSMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.EPSMenu.MenuSelectedFcn = createCallbackFcn(app, @EPSMenuSelected, true);
            app.EPSMenu.Text = 'EPS';
            app.PlotMenu = uimenu(app.PowerEstimateWindowUIFigure);
            app.PlotMenu.Text = '   Plot   ';
            app.ChangePlotPropertiesMenu = uimenu(app.PlotMenu);
            app.ChangePlotPropertiesMenu.Text = 'Change Plot Properties';
            app.PowerEstimatePlotMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.PowerEstimatePlotMenu.Text = 'Power Estimate Plot';
            app.FontSizeMenu = uimenu(app.PowerEstimatePlotMenu);
            app.FontSizeMenu.MenuSelectedFcn = createCallbackFcn(app, @FontSizeMenuSelected, true);
            app.FontSizeMenu.Text = 'FontSize';
            app.BarColorMenu = uimenu(app.PowerEstimatePlotMenu);
            app.BarColorMenu.MenuSelectedFcn = createCallbackFcn(app, @BarColorMenuSelected, true);
            app.BarColorMenu.Text = 'Bar Color';
            app.BackgorundColorMenu = uimenu(app.PowerEstimatePlotMenu);
            app.BackgorundColorMenu.MenuSelectedFcn = createCallbackFcn(app, @BackgorundColorMenuSelected, true);
            app.BackgorundColorMenu.Text = 'Backgorund Color';
            app.XLabelMenu = uimenu(app.PowerEstimatePlotMenu);
            app.XLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @XLabelMenuSelected, true);
            app.XLabelMenu.Text = 'XLabel';
            app.YLabelMenu = uimenu(app.PowerEstimatePlotMenu);
            app.YLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @YLabelMenuSelected, true);
            app.YLabelMenu.Text = 'YLabel';
            app.RESETMenu = uimenu(app.PowerEstimatePlotMenu);
            app.RESETMenu.MenuSelectedFcn = createCallbackFcn(app, @RESETMenuSelected, true);
            app.RESETMenu.Text = 'RESET';
            app.SaveCustomeAppearanceMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.SaveCustomeAppearanceMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveCustomeAppearanceMenuSelected, true);
            app.SaveCustomeAppearanceMenu.Text = 'Save Custome Appearance';
            app.DeleteCustomeAppearanceMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.DeleteCustomeAppearanceMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteCustomeAppearanceMenuSelected, true);
            app.DeleteCustomeAppearanceMenu.Text = 'Delete Custome Appearance';
            app.ExportDataMenu = uimenu(app.PowerEstimateWindowUIFigure);
            app.ExportDataMenu.Text = '   Export Data   ';
            app.matMenu = uimenu(app.ExportDataMenu);
            app.matMenu.MenuSelectedFcn = createCallbackFcn(app, @matMenuSelected, true);
            app.matMenu.Text = '.mat';
            app.txtMenu = uimenu(app.ExportDataMenu);
            app.txtMenu.MenuSelectedFcn = createCallbackFcn(app, @txtMenuSelected, true);
            app.txtMenu.Text = '.txt';
            app.xlsxMenu = uimenu(app.ExportDataMenu);
            app.xlsxMenu.MenuSelectedFcn = createCallbackFcn(app, @xlsxMenuSelected, true);
            app.xlsxMenu.Text = '.xlsx';
            app.UIAxes = uiaxes(app.PowerEstimateWindowUIFigure);
            app.PowerEstimateOptionsPanel = uipanel(app.PowerEstimateWindowUIFigure);
            app.PowerEstimateOptionsPanel.Title = 'Power Estimate Options';
            app.LockYLimCheckBox = uicheckbox(app.PowerEstimateOptionsPanel);
            app.LockYLimCheckBox.ValueChangedFcn = createCallbackFcn(app, @LockYLimCheckBoxValueChanged, true);
            app.LockYLimCheckBox.Text = '   Lock YLim';
            app.LockYLimCheckBox.Value = true;
            app.DataTypeDropDownLabel = uilabel(app.PowerEstimateOptionsPanel);
            app.DataTypeDropDownLabel.Text = 'Data Type';
            app.DataTypeDropDown = uidropdown(app.PowerEstimateOptionsPanel);
            app.DataTypeDropDown.Items = {''};
            app.DataTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DataTypeDropDownValueChanged, true);
            app.DataTypeDropDown.Value = '';
            app.PowerEstimateOptionsLabel = uilabel(app.PowerEstimateOptionsPanel);
            app.PowerEstimateOptionsLabel.Text = 'Power Estimate Options';
            app.AnalysisTimeRangefromtoinsEditFieldLabel = uilabel(app.PowerEstimateOptionsPanel);
            app.AnalysisTimeRangefromtoinsEditFieldLabel.Text = {'Analysis Time'; 'Range '; '[from, to] in s'};
            app.TimeWindowfromtoinsEditField = uieditfield(app.PowerEstimateOptionsPanel, 'text');
            app.TimeWindowfromtoinsEditField.ValueChangedFcn = createCallbackFcn(app, @TimeWindowfromtoinsEditFieldValueChanged, true);
            app.TimeWindowfromtoinsEditField.Enable = 'off';
            app.CoupleTimetoMainWindowCheckBox = uicheckbox(app.PowerEstimateOptionsPanel);
            app.CoupleTimetoMainWindowCheckBox.ValueChangedFcn = createCallbackFcn(app, @CoupleTimetoMainWindowCheckBoxValueChanged, true);
            app.CoupleTimetoMainWindowCheckBox.Text = {'  Couple Time'; '  to Main Window'};
            app.CoupleTimetoMainWindowCheckBox.Value = true;
            app.CloseButton = uibutton(app.PowerEstimateWindowUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed2, true);
            app.CloseButton.Text = 'Close';
            app.ChannelSelectionEditFieldLabel = uilabel(app.PowerEstimateWindowUIFigure);
            app.ChannelSelectionEditFieldLabel.Enable = 'off';
            app.ChannelSelectionEditFieldLabel.Visible = 'off';
            app.ChannelSelectionEditFieldLabel.Text = {'Channel Selection'; ''};
            app.ChannelSelectionPreprocessedChannelEditField = uieditfield(app.PowerEstimateWindowUIFigure, 'text');
            app.ChannelSelectionPreprocessedChannelEditField.Editable = 'off';
            app.ChannelSelectionPreprocessedChannelEditField.Enable = 'off';
            app.ChannelSelectionPreprocessedChannelEditField.Visible = 'off';
            app.Button = uibutton(app.PowerEstimateWindowUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'Open_Figure_Icon.png';
            app.Button.Text = '';
            % Show the figure after all components are created
            app.PowerEstimateWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Spectral_Power_Estimate_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.PowerEstimateWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.PowerEstimateWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PowerEstimateWindowUIFigure)
        end
    end
end