classdef Live_Spectrogram_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpectrogramWindowUIFigure       matlab.ui.Figure
        SnapshotMenu                    matlab.ui.container.Menu
        SaveSnapshotofWindowMenu        matlab.ui.container.Menu
        PNGMenu                         matlab.ui.container.Menu
        JPGMenu                         matlab.ui.container.Menu
        SVGMenu                         matlab.ui.container.Menu
        EPSMenu                         matlab.ui.container.Menu
        PlotMenu                        matlab.ui.container.Menu
        Show2DimensionalPlotsMenu       matlab.ui.container.Menu
        Show3DimensionalPlotsMenu       matlab.ui.container.Menu
        ChangePlotPropertiesMenu        matlab.ui.container.Menu
        SpectrogramPlotMenu             matlab.ui.container.Menu
        WaveletCoherenceMenu            matlab.ui.container.Menu
        ChangePhaseThresholdMenu        matlab.ui.container.Menu
        FontSizeMenu                    matlab.ui.container.Menu
        XLabelMenu                      matlab.ui.container.Menu
        YLabelMenu                      matlab.ui.container.Menu
        CLabelMenu                      matlab.ui.container.Menu
        RESETMenu                       matlab.ui.container.Menu
        SaveCustomeAppearanceMenu       matlab.ui.container.Menu
        DeleteCustomeAppearanceMenu     matlab.ui.container.Menu
        ExportDataMenu                  matlab.ui.container.Menu
        matMenu                         matlab.ui.container.Menu
        txtMenu                         matlab.ui.container.Menu
        xlsxMenu                        matlab.ui.container.Menu
        Button                          matlab.ui.control.Button
        LeftPanel                       matlab.ui.container.Panel
        CloseButton                     matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        FrequencyRangeMinMaxEditField   matlab.ui.control.EditField
        FrequencyRangeMinStepsMaxEditFieldLabel  matlab.ui.control.Label
        WindowsEditField                matlab.ui.control.EditField
        WindowSamplesLabel              matlab.ui.control.Label
        LockCLimCheckBox                matlab.ui.control.CheckBox
        CoupleTimetoMainWindowCheckBox  matlab.ui.control.CheckBox
        ChannelToCompareLabel           matlab.ui.control.Label
        FrequencyRangeminmaxstepsEditField  matlab.ui.control.EditField
        SmoothingEditField              matlab.ui.control.EditField
        WindowSizeSamplesLabel          matlab.ui.control.Label
        AnalysisTypeDropDown            matlab.ui.control.DropDown
        AnalysisTypeDropDownLabel       matlab.ui.control.Label
        TimeWindowfromtoinsEditField    matlab.ui.control.EditField
        AnalysisTimeRangefromtoinsLabel  matlab.ui.control.Label
        ChannelToPlotDropDown           matlab.ui.control.DropDown
        ChannelToPlotDropDownLabel      matlab.ui.control.Label
        SpectrogramOptionsLabel         matlab.ui.control.Label
        DataTypeDropDownLabel           matlab.ui.control.Label
        DataTypeDropDown                matlab.ui.control.DropDown
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Mainapp = []; % Description
        CurrentClim = [];
        TwoORThreeD = "TwoD"; % standard 2d plots when possible
        ExistflagLiveSpectogram = 0;
        Startup = 0;
        ChangeMainPlot = 1;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            movegui(app.SpectrogramWindowUIFigure, 'north');

            app.Mainapp = Mainapp;
            app.Mainapp.LiveSpectrogramApp = app;

            app = Utility_Change_Light_Dark_Mode(app,"LiveSpectrogramWindow");
            
            if strcmp(app.Mainapp.PlotAppearance.MainWindow.Data.Plottype, "Surf") || strcmp(app.Mainapp.PlotAppearance.MainWindow.Data.Plottype,"Mesh")
                app.CoupleTimetoMainWindowCheckBox.Value = 0;
                disp("Disabling couple to main window since just one data point is plotted there in grid activity view mode!");
                app.TimeWindowfromtoinsEditField.Enable = "on";
                if isempty(app.TimeWindowfromtoinsEditField.Value)
                    
                    TimeRangePlot = 1.5;
                    if strcmp(app.DataTypeDropDown.Value,"Preprocessed Data")
                        if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                            StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                        else
                            StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                        end
                    else
                        StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                    end
                    
                    if StartTime<app.Mainapp.Data.Time(end) || StartTime+TimeRangePlot<app.Mainapp.Data.Time(end)
                        app.TimeWindowfromtoinsEditField.Value = strcat(num2str(StartTime),",",num2str(StartTime+TimeRangePlot));
                    else
                        StartTime = app.Mainapp.Data.Time(end)-1;
                        TimeRangePlot = 0.9;
                        app.TimeWindowfromtoinsEditField.Value = strcat(num2str(StartTime),",",num2str(StartTime+TimeRangePlot));
                    end
                end
            end
            if strcmp(app.Mainapp.PlotAppearance.MainWindow.Data.Plottype, "AxonViewer")
                app.CoupleTimetoMainWindowCheckBox.Value = 0;
                disp("Disabling couple to main window since just one data point is plotted there in axon viewer view mode!")
                app.TimeWindowfromtoinsEditField.Enable = "on";
                if isempty(app.TimeWindowfromtoinsEditField.Value)

                    TimeRangePlot = 1.5;
                    if strcmp(app.DataTypeDropDown.Value,"Preprocessed Data")
                        if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                            StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                        else
                            StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                        end
                    else
                        StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                    end

                    if StartTime<app.Mainapp.Data.Time(end) || StartTime+TimeRangePlot<app.Mainapp.Data.Time(end)
                        app.TimeWindowfromtoinsEditField.Value = strcat(num2str(StartTime),",",num2str(StartTime+TimeRangePlot));
                    else
                        StartTime = app.Mainapp.Data.Time(end)-1;
                        TimeRangePlot = 0.9;
                        app.TimeWindowfromtoinsEditField.Value = strcat(num2str(StartTime),",",num2str(StartTime+TimeRangePlot));
                    end
                end
            end

            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.LiveSpectrogramWindow.FontSize;
            app.UIAxes.Color = app.Mainapp.PlotAppearance.LiveSpectrogramWindow.BackgroundColor;
            
            %% Set up app dropdown menus and edit fields
            if isfield(app.Mainapp.Data,'Preprocessed')
                app.DataTypeDropDown.Items{1} = 'Raw Data';
                app.DataTypeDropDown.Items{2} = 'Preprocessed Data';
            else
                app.DataTypeDropDown.Items{1} = 'Raw Data';
            end

            app.FrequencyRangeminmaxstepsEditField.Enable = "off";
            app.SmoothingEditField.Enable = "off";
            
            % Channel selection
            AllChannels = {};
            if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 1
                TempActiveChannel = (str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)*str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)+1)-sort(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
                for i = 1:length(TempActiveChannel)
                    AllChannels{i} = num2str(TempActiveChannel(i));
                end
            else
                for i = 1:length(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel)
                    AllChannels{i} = num2str(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel(i));
                end
            end
            
            app.ChannelToPlotDropDown.Items = AllChannels;
            if app.Mainapp.Data.Info.NativeSamplingRate >=1000
                app.FrequencyRangeMinMaxEditField.Value = strcat("1,100,220");
            else
                app.FrequencyRangeMinMaxEditField.Value = strcat("1,",num2str(app.Mainapp.Data.Info.NativeSamplingRate/2));
            end
            
            % Y axis reverse
            app.UIAxes.YDir = 'reverse';
            
            % flags for following plot function to know when to update
            app.Startup = 1;
            app.ExistflagLiveSpectogram = 1;
            app.ChangeMainPlot = 0;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1;
        end

        % Close request function: SpectrogramWindowUIFigure
        function SpectrogramWindowUIFigureCloseRequest(app, event)
            try
                app.ChangeMainPlot = 1;
                app.Mainapp.LiveSpectrogramApp = [];
                
                if ~isempty(app.Mainapp.ProbeViewWindowHandle) % delete option from probe view 
                    for i = 1:length(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                        if strcmp(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Main Plot Spectrogram')
                            app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items(i) = [];
                            break;
                        end
                    end
                else % if no probe view available
     
                end
                app.ExistflagLiveSpectogram = 0;
            catch

            end
            delete(app)
        end

        % Callback function
        function ChannelSpacingumEditFieldValueChanged(app, event)
            
        end

        % Callback function
        function HammWindowEditFieldValueChanged(app, event)
           
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            try
                app.ChangeMainPlot = 1;
                app.Mainapp.LiveSpectrogramApp = [];
                
                if ~isempty(app.Mainapp.ProbeViewWindowHandle) % delete option from probe view 
                    for i = 1:length(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                        if strcmp(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Main Plot Spectrogram')
                            app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items(i) = [];
                            break;
                        end
                    end
                else % if no probe view available
     
                end
            end
            app.ExistflagLiveSpectogram = 0;
            delete(app)
        end

        % Callback function
        function ChannelSelectionfromtoEditFieldValueChanged(app, event)
          
        end

        % Value changed function: FrequencyRangeMinMaxEditField
        function FrequencyRangeMinMaxEditFieldValueChanged2(app, event)
            app.ChangeMainPlot = 0;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1;
        end

        % Value changed function: LockCLimCheckBox
        function LockCLimCheckBoxValueChanged(app, event)
            app.ChangeMainPlot = 0;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1;
        end

        % Menu selected function: PNGMenu
        function PNGMenuSelected(app, event)
            [file, path] = uiputfile('*.png', 'Save as');

            exportapp(app.SpectrogramWindowUIFigure,fullfile(path,file)) 
        end

        % Menu selected function: JPGMenu
        function JPGMenuSelected(app, event)
            [file, path] = uiputfile('*.jpg', 'Save as');

            exportapp(app.SpectrogramWindowUIFigure,fullfile(path,file)) 
        end

        % Menu selected function: Show2DimensionalPlotsMenu
        function Show2DimensionalPlotsMenuSelected(app, event)
            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";

            app.UIAxes.YDir = 'reverse';

            app.TwoORThreeD = "TwoD";
            
            app.ChangeMainPlot = 0;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1;

            app.UIAxes.Title.Color = 'k';
            app.UIAxes.XLabel.Color = 'k';
            app.UIAxes.YLabel.Color = 'k';
            app.UIAxes.XColor = 'k';  
            app.UIAxes.YColor = 'k';  
            cb = colorbar(app.UIAxes);
            cb.Color = 'k';              % Sets tick mark and label color to black
            cb.Label.String = app.Mainapp.PlotAppearance.LiveSpectrogramWindow.CLabel;
            cb.Label.Rotation = 270;
            cb.FontSize =  app.Mainapp.PlotAppearance.LiveSpectrogramWindow.FontSize-1;
            cb.Label.Color = 'k';        % Sets the color of the label text
        end

        % Menu selected function: matMenu
        function matMenuSelected(app, event)
            TimeRangePlot = str2double(app.Mainapp.TimeRangeViewBox.Value(1:end-1));
            if strcmp(app.DataTypeDropDown.Value,"Preprocessed Data")
                if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                else
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                end
            else
                StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
            end
            
            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Live Spectrogram Plot",0);
        end

        % Menu selected function: txtMenu
        function txtMenuSelected(app, event)
            TimeRangePlot = str2double(app.Mainapp.TimeRangeViewBox.Value(1:end-1));
            if strcmp(app.DataTypeDropDown.Value,"Preprocessed Data")
                if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                else
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                end
            else
                StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
            end

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Live Spectrogram Plot",0);
        end

        % Menu selected function: Show3DimensionalPlotsMenu
        function Show3DimensionalPlotsMenuSelected(app, event)
            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";
            
            app.UIAxes.YDir = 'reverse';

 
            app.TwoORThreeD = "ThreeD";

            app.ChangeMainPlot = 0;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1;

            app.UIAxes.Title.Color = 'k';
            app.UIAxes.XLabel.Color = 'k';
            app.UIAxes.YLabel.Color = 'k';
            app.UIAxes.XColor = 'k';  
            app.UIAxes.YColor = 'k';  
            app.UIAxes.ZColor = 'k';  
            cb = colorbar(app.UIAxes);
            cb.Color = 'k';              % Sets tick mark and label color to black
            cb.Label.String = app.Mainapp.PlotAppearance.LiveSpectrogramWindow.CLabel;
            cb.Label.Rotation = 270;
            cb.FontSize =  app.Mainapp.PlotAppearance.LiveSpectrogramWindow.FontSize-1;
            cb.Label.Color = 'k';        % Sets the color of the label text

        end

        % Menu selected function: FontSizeMenu
        function FontSizeMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'fontsize',app.Mainapp,"LiveSpecWindowDataFontSize");
            waitfor(h);

            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.LiveSpectrogramWindow.FontSize;

        end

        % Menu selected function: XLabelMenu
        function XLabelMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'xlabel',app.Mainapp,"LiveSpecWindowXLabel");
            waitfor(h);
            app.ChangeMainPlot = 0;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1;
        end

        % Menu selected function: YLabelMenu
        function YLabelMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'ylabel',app.Mainapp,"LiveSpecWindowYLabel");
            waitfor(h);
            app.ChangeMainPlot = 0;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1;
        end

        % Menu selected function: CLabelMenu
        function CLabelMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'clabel',app.Mainapp,"LiveSpecWindowCLabel");
            waitfor(h);

            cb = colorbar(app.UIAxes);
            cb.Color = 'k';              % Sets tick mark and label color to black
            cb.Label.String = app.Mainapp.PlotAppearance.LiveSpectrogramWindow.CLabel;

        end

        % Menu selected function: RESETMenu
        function RESETMenuSelected(app, event)
            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";

            app.UIAxes.YDir = 'reverse';

            [app.Mainapp.PlotAppearance] = Organize_Set_Standard_PlotAppearance("LiveSpectrogramPlot",app.Mainapp.PlotAppearance);
            app.ChangeMainPlot = 0;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1;
            app.UIAxes.Title.Color = 'k';
            app.UIAxes.XLabel.Color = 'k';
            app.UIAxes.YLabel.Color = 'k';
            app.UIAxes.XColor = 'k';  
            app.UIAxes.YColor = 'k';  

            cb = colorbar(app.UIAxes);
            cb.Color = 'k';              % Sets tick mark and label color to black
            cb.Label.String = app.Mainapp.PlotAppearance.LiveSpectrogramWindow.CLabel;
            cb.Label.Rotation = 270;
            cb.FontSize =  app.Mainapp.PlotAppearance.LiveSpectrogramWindow.FontSize-1;
            cb.Label.Color = 'k';        % Sets the color of the label text
        end

        % Menu selected function: SaveCustomeAppearanceMenu
        function SaveCustomeAppearanceMenuSelected(app, event)
            app.Mainapp.PlotAppearance = Utility_Save_Load_Delete_Plot_Appearance(app.Mainapp.PlotAppearance,app.Mainapp.executableFolder,"Save");
        end

        % Menu selected function: DeleteCustomeAppearanceMenu
        function DeleteCustomeAppearanceMenuSelected(app, event)
            app.Mainapp.PlotAppearance = Utility_Save_Load_Delete_Plot_Appearance(app.Mainapp.PlotAppearance,app.Mainapp.executableFolder,"Delete");
        end

        % Value changed function: DataTypeDropDown
        function DataTypeDropDownValueChanged(app, event)
            % if strcmp(app.DataTypeDropDown.Value,"Preprocessed Data")
            %     if isfield(app.Mainapp.Data.Info,"DownsampleFactor")
            %         Currentwindow = str2double(app.WindowsEditField.Value);
            %         if Currentwindow > app.Mainapp.Data.Info.DownsampledSampleRate/2
            %             app.WindowsEditField.Value = num2str(app.Mainapp.Data.Info.DownsampledSampleRate/4);
            %         end
            %     end
            % end

            app.CurrentClim = [];
            app.ChangeMainPlot = 0;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1;

            app.UIAxes.Title.Color = 'k';
            app.UIAxes.XLabel.Color = 'k';
            app.UIAxes.YLabel.Color = 'k';
            app.UIAxes.XColor = 'k';  
            app.UIAxes.YColor = 'k';  
            cb = colorbar(app.UIAxes);
            cb.Color = 'k';              % Sets tick mark and label color to black
            cb.Label.String = app.Mainapp.PlotAppearance.LiveSpectrogramWindow.CLabel;
            cb.Label.Rotation = 270;
            cb.FontSize =  app.Mainapp.PlotAppearance.LiveSpectrogramWindow.FontSize-1;
            cb.Label.Color = 'k';        % Sets the color of the label text
        end

        % Menu selected function: SVGMenu
        function SVGMenuSelected(app, event)
            [file, path] = uiputfile('*.svg', 'Save as');
            
            exportgraphics(app.SpectrogramWindowUIFigure, fullfile(path,file), 'ContentType', 'vector');
        end

        % Menu selected function: xlsxMenu
        function xlsxMenuSelected(app, event)
            TimeRangePlot = str2double(app.Mainapp.TimeRangeViewBox.Value(1:end-1));
            if strcmp(app.DataTypeDropDown.Value,"Preprocessed Data")
                if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                else
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                end
            else
                StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
            end

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".xlsx",app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Live Spectrogram Plot",0);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            Utility_Open_UIAxes_as_Figure(app.Mainapp.Data,app.UIAxes,"LiveSpectogram");
        end

        % Value changed function: ChannelToPlotDropDown
        function ChannelToPlotDropDownValueChanged(app, event)
            app.ChangeMainPlot = 0;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1;
        end

        % Value changed function: WindowsEditField
        function WindowsEditFieldValueChanged(app, event)
            app.ChangeMainPlot = 0;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1;
        end

        % Value changed function: CoupleTimetoMainWindowCheckBox
        function CoupleTimetoMainWindowCheckBoxValueChanged(app, event)
            value = app.CoupleTimetoMainWindowCheckBox.Value;
            if value
                if strcmp(app.Mainapp.PlotAppearance.MainWindow.Data.Plottype, "Surf") || strcmp(app.Mainapp.PlotAppearance.MainWindow.Data.Plottype,"Mesh")
                    app.CoupleTimetoMainWindowCheckBox.Value = 0;
                    disp("Disabling couple to main window since just one data point is plotted there in grid activity view mode!");
                    return;
                end
                if strcmp(app.Mainapp.PlotAppearance.MainWindow.Data.Plottype, "AxonViewer")
                    app.CoupleTimetoMainWindowCheckBox.Value = 0;
                    disp("Disabling couple to main window since just one data point is plotted there in axon viewer view mode!")
                    return;
                end
                app.TimeWindowfromtoinsEditField.Enable = "off";
            else
                app.TimeWindowfromtoinsEditField.Enable = "on";
                if isempty(app.TimeWindowfromtoinsEditField.Value)

                    TimeRangePlot = str2double(app.Mainapp.TimeRangeViewBox.Value(1:end-1));
                    if strcmp(app.DataTypeDropDown.Value,"Preprocessed Data")
                        if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                            StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                        else
                            StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                        end
                    else
                        StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                    end

                    app.TimeWindowfromtoinsEditField.Value = strcat(num2str(StartTime),",",num2str(StartTime+TimeRangePlot));
                end
            end

            app.ChangeMainPlot = 0;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1;
        end

        % Value changed function: TimeWindowfromtoinsEditField
        function TimeWindowfromtoinsEditFieldValueChanged(app, event)
            app.ChangeMainPlot = 0;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1;
        end

        % Value changed function: AnalysisTypeDropDown
        function AnalysisTypeDropDownValueChanged(app, event)
            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0)
            cla(app.UIAxes)
            app.UIAxes.NextPlot = "add";
            app.UIAxes.YScale = 'linear';
            app.UIAxes.YTickMode = 'auto';
            app.UIAxes.YTickLabelMode = 'auto';

            Freqs = str2double(strsplit(app.FrequencyRangeMinMaxEditField.Value,','));

            if Freqs(2)>12 && strcmp(app.AnalysisTypeDropDown.Value,"Time Varying Wavelet Coherence")
                Freqs(2) = 12;
                app.FrequencyRangeMinMaxEditField.Value = strjoin(string(Freqs),',');
            end

            if strcmp(app.AnalysisTypeDropDown.Value,"Single Channel ERP Spectogram SFFT")
                app.FrequencyRangeminmaxstepsEditField.Enable = "off";
                app.SmoothingEditField.Enable = "off";
                app.ChannelToPlotDropDown.Enable = "on";
                app.WindowsEditField.Enable = "on";
            elseif strcmp(app.AnalysisTypeDropDown.Value,"Single Channel ERP Spectogram Moorlet Wavelets")
                app.FrequencyRangeminmaxstepsEditField.Enable = "off";
                app.SmoothingEditField.Enable = "on";
                app.WindowsEditField.Enable = "off";
                app.ChannelToPlotDropDown.Enable = "on";
            elseif strcmp(app.AnalysisTypeDropDown.Value,"Time Varying Wavelet Coherence")
                app.FrequencyRangeminmaxstepsEditField.Enable = "on";
                app.SmoothingEditField.Enable = "on";
                app.ChannelToPlotDropDown.Enable = "off";
                app.WindowsEditField.Enable = "on";
            end

            cla(app.UIAxes);

            app.CurrentClim = [];
            app.ChangeMainPlot = 0;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1;

            app.UIAxes.Title.Color = 'k';
            app.UIAxes.XLabel.Color = 'k';
            app.UIAxes.YLabel.Color = 'k';
            app.UIAxes.XColor = 'k';  
            app.UIAxes.YColor = 'k';  
            cb = colorbar(app.UIAxes);
            cb.Color = 'k';              % Sets tick mark and label color to black
            cb.Label.String = app.Mainapp.PlotAppearance.LiveSpectrogramWindow.CLabel;
            cb.Label.Rotation = 270;
            cb.FontSize =  app.Mainapp.PlotAppearance.LiveSpectrogramWindow.FontSize-1;
            cb.Label.Color = 'k';        % Sets the color of the label text
        end

        % Value changed function: SmoothingEditField
        function SmoothingEditFieldValueChanged(app, event)
            app.ChangeMainPlot = 0;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1;
            
        end

        % Value changed function: FrequencyRangeminmaxstepsEditField
        function FrequencyRangeminmaxstepsEditFieldValueChanged(app, event)
            app.ChangeMainPlot = 0;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1;
            
        end

        % Menu selected function: ChangePhaseThresholdMenu
        function ChangePhaseThresholdMenuSelected(app, event)
            h = Channel_Spacing_Selection(app,"LiveTF Phase Threshold");
            waitfor(h);

            app.ChangeMainPlot = 0;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1;
        end

        % Menu selected function: EPSMenu
        function EPSMenuSelected(app, event)
            [file, path] = uiputfile('*.eps', 'Save as');

            exportgraphics(app.UIAxes, fullfile(path,file), 'ContentType', 'vector')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpectrogramWindowUIFigure = uifigure('Visible', 'off');
            app.SpectrogramWindowUIFigure.Name = 'Live Spectrogram Window';
            app.SpectrogramWindowUIFigure.Icon = 'Logo.png';
            app.SpectrogramWindowUIFigure.CloseRequestFcn = createCallbackFcn(app, @SpectrogramWindowUIFigureCloseRequest, true);
            app.SnapshotMenu = uimenu(app.SpectrogramWindowUIFigure);
            app.SnapshotMenu.Text = '   Snapshot   ';
            app.SaveSnapshotofWindowMenu = uimenu(app.SnapshotMenu);
            app.SaveSnapshotofWindowMenu.Text = 'Save Snapshot of Window';
            app.PNGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.PNGMenu.MenuSelectedFcn = createCallbackFcn(app, @PNGMenuSelected, true);
            app.PNGMenu.Text = 'PNG';
            app.JPGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.JPGMenu.MenuSelectedFcn = createCallbackFcn(app, @JPGMenuSelected, true);
            app.JPGMenu.Text = 'JPG';
            app.SVGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.SVGMenu.MenuSelectedFcn = createCallbackFcn(app, @SVGMenuSelected, true);
            app.SVGMenu.Text = 'SVG';
            app.EPSMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.EPSMenu.MenuSelectedFcn = createCallbackFcn(app, @EPSMenuSelected, true);
            app.EPSMenu.Text = 'EPS';
            app.PlotMenu = uimenu(app.SpectrogramWindowUIFigure);
            app.PlotMenu.Text = '   Plot   ';
            app.Show2DimensionalPlotsMenu = uimenu(app.PlotMenu);
            app.Show2DimensionalPlotsMenu.MenuSelectedFcn = createCallbackFcn(app, @Show2DimensionalPlotsMenuSelected, true);
            app.Show2DimensionalPlotsMenu.Text = 'Show 2 Dimensional Plots';
            app.Show3DimensionalPlotsMenu = uimenu(app.PlotMenu);
            app.Show3DimensionalPlotsMenu.MenuSelectedFcn = createCallbackFcn(app, @Show3DimensionalPlotsMenuSelected, true);
            app.Show3DimensionalPlotsMenu.Text = 'Show 3 Dimensional Plots';
            app.ChangePlotPropertiesMenu = uimenu(app.PlotMenu);
            app.ChangePlotPropertiesMenu.Text = 'Change Plot Properties';
            app.SpectrogramPlotMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.SpectrogramPlotMenu.Text = 'Spectrogram Plot';
            app.WaveletCoherenceMenu = uimenu(app.SpectrogramPlotMenu);
            app.WaveletCoherenceMenu.Text = 'Wavelet Coherence';
            app.ChangePhaseThresholdMenu = uimenu(app.WaveletCoherenceMenu);
            app.ChangePhaseThresholdMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangePhaseThresholdMenuSelected, true);
            app.ChangePhaseThresholdMenu.Text = 'Change Phase Threshold';
            app.FontSizeMenu = uimenu(app.SpectrogramPlotMenu);
            app.FontSizeMenu.MenuSelectedFcn = createCallbackFcn(app, @FontSizeMenuSelected, true);
            app.FontSizeMenu.Text = 'FontSize';
            app.XLabelMenu = uimenu(app.SpectrogramPlotMenu);
            app.XLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @XLabelMenuSelected, true);
            app.XLabelMenu.Text = 'XLabel';
            app.YLabelMenu = uimenu(app.SpectrogramPlotMenu);
            app.YLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @YLabelMenuSelected, true);
            app.YLabelMenu.Text = 'YLabel';
            app.CLabelMenu = uimenu(app.SpectrogramPlotMenu);
            app.CLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @CLabelMenuSelected, true);
            app.CLabelMenu.Text = 'CLabel';
            app.RESETMenu = uimenu(app.SpectrogramPlotMenu);
            app.RESETMenu.MenuSelectedFcn = createCallbackFcn(app, @RESETMenuSelected, true);
            app.RESETMenu.Text = 'RESET';
            app.SaveCustomeAppearanceMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.SaveCustomeAppearanceMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveCustomeAppearanceMenuSelected, true);
            app.SaveCustomeAppearanceMenu.Text = 'Save Custome Appearance';
            app.DeleteCustomeAppearanceMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.DeleteCustomeAppearanceMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteCustomeAppearanceMenuSelected, true);
            app.DeleteCustomeAppearanceMenu.Text = 'Delete Custome Appearance';
            app.ExportDataMenu = uimenu(app.SpectrogramWindowUIFigure);
            app.ExportDataMenu.Text = '   Export Data   ';
            app.matMenu = uimenu(app.ExportDataMenu);
            app.matMenu.MenuSelectedFcn = createCallbackFcn(app, @matMenuSelected, true);
            app.matMenu.Text = '.mat';
            app.txtMenu = uimenu(app.ExportDataMenu);
            app.txtMenu.MenuSelectedFcn = createCallbackFcn(app, @txtMenuSelected, true);
            app.txtMenu.Text = '.txt';
            app.xlsxMenu = uimenu(app.ExportDataMenu);
            app.xlsxMenu.MenuSelectedFcn = createCallbackFcn(app, @xlsxMenuSelected, true);
            app.xlsxMenu.Text = '.xlsx';
            app.UIAxes = uiaxes(app.SpectrogramWindowUIFigure);
            app.LeftPanel = uipanel(app.SpectrogramWindowUIFigure);
            app.Panel = uipanel(app.LeftPanel);
            app.DataTypeDropDown = uidropdown(app.Panel);
            app.DataTypeDropDown.Items = {};
            app.DataTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DataTypeDropDownValueChanged, true);
            app.DataTypeDropDown.Value = {};
            app.DataTypeDropDownLabel = uilabel(app.Panel);
            app.DataTypeDropDownLabel.Text = 'Data Type';
            app.SpectrogramOptionsLabel = uilabel(app.Panel);
            app.SpectrogramOptionsLabel.Text = 'Spectrogram Options';
            app.ChannelToPlotDropDownLabel = uilabel(app.Panel);
            app.ChannelToPlotDropDownLabel.Text = 'Channel To Plot';
            app.ChannelToPlotDropDown = uidropdown(app.Panel);
            app.ChannelToPlotDropDown.Items = {};
            app.ChannelToPlotDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelToPlotDropDownValueChanged, true);
            app.ChannelToPlotDropDown.Value = {};
            app.AnalysisTimeRangefromtoinsLabel = uilabel(app.Panel);
            app.AnalysisTimeRangefromtoinsLabel.Text = {'Time Range '; '[from, to] in s'};
            app.TimeWindowfromtoinsEditField = uieditfield(app.Panel, 'text');
            app.TimeWindowfromtoinsEditField.ValueChangedFcn = createCallbackFcn(app, @TimeWindowfromtoinsEditFieldValueChanged, true);
            app.TimeWindowfromtoinsEditField.Enable = 'off';
            app.AnalysisTypeDropDownLabel = uilabel(app.Panel);
            app.AnalysisTypeDropDownLabel.Text = 'Analysis Type';
            app.AnalysisTypeDropDown = uidropdown(app.Panel);
            app.AnalysisTypeDropDown.Items = {'Single Channel ERP Spectogram SFFT', 'Single Channel ERP Spectogram Moorlet Wavelets', 'Time Varying Wavelet Coherence'};
            app.AnalysisTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @AnalysisTypeDropDownValueChanged, true);
            app.AnalysisTypeDropDown.Value = 'Single Channel ERP Spectogram SFFT';
            app.WindowSizeSamplesLabel = uilabel(app.Panel);
            app.WindowSizeSamplesLabel.Text = {'Smoothing'; '(Nr Scales)'};
            app.SmoothingEditField = uieditfield(app.Panel, 'text');
            app.SmoothingEditField.ValueChangedFcn = createCallbackFcn(app, @SmoothingEditFieldValueChanged, true);
            app.SmoothingEditField.Value = '9';
            app.FrequencyRangeminmaxstepsEditField = uieditfield(app.Panel, 'text');
            app.FrequencyRangeminmaxstepsEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyRangeminmaxstepsEditFieldValueChanged, true);
            app.FrequencyRangeminmaxstepsEditField.Value = '1,2';
            app.ChannelToCompareLabel = uilabel(app.Panel);
            app.ChannelToCompareLabel.Text = {'Channel To'; 'Compare'};
            app.CoupleTimetoMainWindowCheckBox = uicheckbox(app.Panel);
            app.CoupleTimetoMainWindowCheckBox.ValueChangedFcn = createCallbackFcn(app, @CoupleTimetoMainWindowCheckBoxValueChanged, true);
            app.CoupleTimetoMainWindowCheckBox.Text = {'  Couple Time'; '  to Main Window'};
            app.CoupleTimetoMainWindowCheckBox.Value = true;
            app.LockCLimCheckBox = uicheckbox(app.Panel);
            app.LockCLimCheckBox.ValueChangedFcn = createCallbackFcn(app, @LockCLimCheckBoxValueChanged, true);
            app.LockCLimCheckBox.Text = '  Lock C Lim';
            app.LockCLimCheckBox.Value = true;
            app.WindowSamplesLabel = uilabel(app.Panel);
            app.WindowSamplesLabel.Text = 'Window Length [Samples]';
            app.WindowsEditField = uieditfield(app.Panel, 'text');
            app.WindowsEditField.ValueChangedFcn = createCallbackFcn(app, @WindowsEditFieldValueChanged, true);
            app.WindowsEditField.Value = '400';
            app.FrequencyRangeMinStepsMaxEditFieldLabel = uilabel(app.Panel);
            app.FrequencyRangeMinStepsMaxEditFieldLabel.Text = {'Frequency Range'; '[Min,Steps,Max]'};
            app.FrequencyRangeMinMaxEditField = uieditfield(app.Panel, 'text');
            app.FrequencyRangeMinMaxEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyRangeMinMaxEditFieldValueChanged2, true);
            app.FrequencyRangeMinMaxEditField.Value = '1,200,200';
            app.CloseButton = uibutton(app.LeftPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.Button = uibutton(app.SpectrogramWindowUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'Open_Figure_Icon.png';
            app.Button.Text = '';
            % Show the figure after all components are created
            app.SpectrogramWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Live_Spectrogram_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SpectrogramWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.SpectrogramWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpectrogramWindowUIFigure)
        end
    end
end