classdef Spike_Rate_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpikeRateWindowUIFigure         matlab.ui.Figure
        SnapshotMenu                    matlab.ui.container.Menu
        SaveSnapshotofWindowMenu        matlab.ui.container.Menu
        PNGMenu                         matlab.ui.container.Menu
        JPGMenu                         matlab.ui.container.Menu
        SVGMenu                         matlab.ui.container.Menu
        EPSMenu                         matlab.ui.container.Menu
        PlotMenu                        matlab.ui.container.Menu
        ChangePlotPropertiesMenu        matlab.ui.container.Menu
        SpikeRatePlotMenu               matlab.ui.container.Menu
        FontSizeMenu                    matlab.ui.container.Menu
        BarColorMenu                    matlab.ui.container.Menu
        BackgorundColorMenu             matlab.ui.container.Menu
        XLabelMenu                      matlab.ui.container.Menu
        YLabelMenu                      matlab.ui.container.Menu
        RESETMenu                       matlab.ui.container.Menu
        SaveCustomeAppearanceMenu       matlab.ui.container.Menu
        DeleteCustomeAppearanceMenu     matlab.ui.container.Menu
        ExportDataMenu                  matlab.ui.container.Menu
        matMenu                         matlab.ui.container.Menu
        txtMenu                         matlab.ui.container.Menu
        xlsxMenu                        matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        ChannelSelectionPreprocessedChannelEditField  matlab.ui.control.EditField
        ChannelSelectionPreprocessedChannelLabel  matlab.ui.control.Label
        Button                          matlab.ui.control.Button
        CloseButton                     matlab.ui.control.Button
        SpikeRateOptionsPanel           matlab.ui.container.Panel
        CoupleTimetoMainWindowCheckBox  matlab.ui.control.CheckBox
        TimeWindowfromtoinsEditField    matlab.ui.control.EditField
        TimeWindowfromtoinsEditFieldLabel  matlab.ui.control.Label
        Slider                          matlab.ui.control.Slider
        NrofBinsLabel                   matlab.ui.control.Label
        SpikeRateOptionsLabel           matlab.ui.control.Label
        LockYLimCheckBox                matlab.ui.control.CheckBox
        DownsampleCheckBox              matlab.ui.control.CheckBox
        RightPanel                      matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        ContextMenu4                    matlab.ui.container.ContextMenu
        ChangeCutoffFrequencyMenu       matlab.ui.container.Menu
        ChangeFilterOrderMenu           matlab.ui.container.Menu
        ContextMenu5                    matlab.ui.container.ContextMenu
        ChangeSliderLimitsMenu          matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Mainapp = []; % Description
        Existflag =  [];
        SpikeYLim = [];
        FilterOrder = 1;
        CutoffFreque = [];
        Startup = 1;
        ChangeMainPlot = 1;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            movegui(app.SpikeRateWindowUIFigure, 'north');

            app.Mainapp = Mainapp;
            app.Mainapp.PSTHApp = app;

            app = Utility_Change_Light_Dark_Mode(app,"LiveSpikeRatePowerWindow");
            
            if strcmp(app.Mainapp.PlotAppearance.MainWindow.Data.Plottype, "Surf") || strcmp(app.Mainapp.PlotAppearance.MainWindow.Data.Plottype,"Mesh")
                app.CoupleTimetoMainWindowCheckBox.Value = 0;
                disp("Disabling couple to main window since just one data point is plotted there in grid activity view mode!");
                app.TimeWindowfromtoinsEditField.Enable = "on";
                if isempty(app.TimeWindowfromtoinsEditField.Value)
                    
                    TimeRangePlot = 1.5;
                    if contains(app.Mainapp.DropDown.Value,"Preprocessed Data")
                        if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                            StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                        else
                            StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                        end
                    else
                        StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                    end
                    
                    if StartTime<app.Mainapp.Data.Time(end) || StartTime+TimeRangePlot<app.Mainapp.Data.Time(end)
                        app.TimeWindowfromtoinsEditField.Value = strcat(num2str(StartTime),",",num2str(StartTime+TimeRangePlot));
                    else
                        StartTime = app.Mainapp.Data.Time(end)-1;
                        TimeRangePlot = 0.9;
                        app.TimeWindowfromtoinsEditField.Value = strcat(num2str(StartTime),",",num2str(StartTime+TimeRangePlot));
                    end
                end
            end
            if strcmp(app.Mainapp.PlotAppearance.MainWindow.Data.Plottype, "AxonViewer")
                app.CoupleTimetoMainWindowCheckBox.Value = 0;
                disp("Disabling couple to main window since just one data point is plotted there in axon viewer view mode!")
                app.TimeWindowfromtoinsEditField.Enable = "on";
                if isempty(app.TimeWindowfromtoinsEditField.Value)

                    TimeRangePlot = 1.5;
                    if contains(app.Mainapp.DropDown.Value,"Preprocessed Data")
                        if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                            StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                        else
                            StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                        end
                    else
                        StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                    end

                    if StartTime<app.Mainapp.Data.Time(end) || StartTime+TimeRangePlot<app.Mainapp.Data.Time(end)
                        app.TimeWindowfromtoinsEditField.Value = strcat(num2str(StartTime),",",num2str(StartTime+TimeRangePlot));
                    else
                        StartTime = app.Mainapp.Data.Time(end)-1;
                        TimeRangePlot = 0.9;
                        app.TimeWindowfromtoinsEditField.Value = strcat(num2str(StartTime),",",num2str(StartTime+TimeRangePlot));
                    end
                end
            end

            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.LiveSpikeRateWindow.FontSize;
            app.UIAxes.Color = app.Mainapp.PlotAppearance.LiveSpikeRateWindow.BackgroundColor;

            if ~isfield(app.Mainapp.Data,'Spikes') 
                f = msgbox("Error: No Spike data detected. Please first extract spikes from your dataset!");
                app.Mainapp.PSTHApp = [];
            
                if ~isempty(app.Mainapp.ProbeViewWindowHandle) % delete option from probe view 
                    for i = 1:length(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                        if strcmp(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Main Plot Spike Rate')
                            app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items(i) = [];
                            break;
                        end
                    end
                else % if no probe view available
     
                end
                delete(app);
                return;
            else
                if isempty(app.Mainapp.Data.Spikes)
                f = msgbox("Error: No Spike data detected. Please first extract spikes from your dataset!");
                app.Mainapp.PSTHApp = [];
            
                if ~isempty(app.Mainapp.ProbeViewWindowHandle) % delete option from probe view 
                    for i = 1:length(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                        if strcmp(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Main Plot Spike Rate')
                            app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items(i) = [];
                            break;
                        end
                    end
                else % if no probe view available
     
                end
                delete(app);
                return;
                end
            end
    
            if strcmp(app.Mainapp.Data.Info.SpikeType,'Kilosort')
                app.ChannelSelectionPreprocessedChannelEditField.Enable = "off";
                app.ChannelSelectionPreprocessedChannelLabel.Text = "Kilosort Spikes only for all channel";
            end

            app.ChannelSelectionPreprocessedChannelEditField.Value = strcat(num2str(1),",",num2str(size(app.Mainapp.Data.Raw,1)));
            app.Existflag = 1;
            
            
            app.Mainapp.PSTHApp = app;
            app.ChangeMainPlot = 0;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1;
            app.Startup = 0;
        end

        % Close request function: SpikeRateWindowUIFigure
        function SpikeRateWindowUIFigureCloseRequest(app, event)
            try
                app.ChangeMainPlot = 1;
                app.Mainapp.PSTHApp = [];
                app.Existflag = 0;
                if ~isempty(app.Mainapp.ProbeViewWindowHandle) % delete option from probe view 
                    for i = 1:length(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                        if strcmp(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Main Plot Spike Rate')
                            app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items(i) = [];
                            break;
                        end
                    end
                else % if no probe view available
     
                end                
            end

            delete(app)
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.Mainapp.PSTHApp = app;
            app.ChangeMainPlot = 0;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1;
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            try
                app.ChangeMainPlot = 1;
                app.Mainapp.PSTHApp = [];
                app.Existflag = 0;
                if ~isempty(app.Mainapp.ProbeViewWindowHandle) % delete option from probe view 
                    for i = 1:length(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                        if strcmp(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Main Plot Spike Rate')
                            app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items(i) = [];
                            break;
                        end
                    end
                else % if no probe view available
     
                end                
            end

            delete(app)
        end

        % Value changed function: LockYLimCheckBox
        function LockYLimCheckBoxValueChanged(app, event)
          
        end

        % Value changed function: 
        % ChannelSelectionPreprocessedChannelEditField
        function ChannelSelectionPreprocessedChannelEditFieldValueChanged(app, event)
           if isfield(app.Mainapp.Data,"Preprocessed")
                [app.ChannelSelectionPreprocessedChannelEditField.Value] = Utility_SimpleCheckInputs(app.ChannelSelectionPreprocessedChannelEditField.Value,"Two",strcat('1,',num2str(size(app.Mainapp.Data.Preprocessed,1))),1,0);
           else
                [app.ChannelSelectionPreprocessedChannelEditField.Value] = Utility_SimpleCheckInputs(app.ChannelSelectionPreprocessedChannelEditField.Value,"Two",strcat('1,',num2str(size(app.Mainapp.Data.Raw,1))),1,0);
           end
           
           app.Mainapp.PSTHApp = app;
           app.ChangeMainPlot = 0;
           Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
           app.ChangeMainPlot = 1;
        end

        % Menu selected function: PNGMenu
        function PNGMenuSelected(app, event)
            [file, path] = uiputfile('*.png', 'Save as');

            exportapp(app.SpikeRateWindowUIFigure,fullfile(path,file)) 
        end

        % Menu selected function: JPGMenu
        function JPGMenuSelected(app, event)
            [file, path] = uiputfile('*.jpg', 'Save as');

            exportapp(app.SpikeRateWindowUIFigure,fullfile(path,file)) 
        end

        % Value changed function: DownsampleCheckBox
        function DownsampleCheckBoxValueChanged(app, event)
            app.Mainapp.PSTHApp = app;
            app.ChangeMainPlot = 0;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1;
        end

        % Menu selected function: ChangeCutoffFrequencyMenu
        function ChangeCutoffFrequencyMenuSelected(app, event)
            Change_SpikeRate_Filter_Options_Window(app,"Cutoff");
            
        end

        % Menu selected function: ChangeFilterOrderMenu
        function ChangeFilterOrderMenuSelected(app, event)
            Change_SpikeRate_Filter_Options_Window(app,"FilterOrder");
            app.ChangeMainPlot = 0;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1;
        end

        % Menu selected function: matMenu
        function matMenuSelected(app, event)
            TimeRangePlot = str2double(app.Mainapp.TimeRangeViewBox.Value(1:end-1));
            if strcmp(app.Mainapp.DropDown.Value,"Preprocessed Data")
                if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                else
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                end
            else
                StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
            end

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Live_Spike_Rate_Main_Window",0);
        end

        % Menu selected function: txtMenu
        function txtMenuSelected(app, event)
            TimeRangePlot = str2double(app.Mainapp.TimeRangeViewBox.Value(1:end-1));
            if strcmp(app.Mainapp.DropDown.Value,"Preprocessed Data")
                if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                else
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                end
            else
                StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
            end

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Live_Spike_Rate_Main_Window",0);
        end

        % Menu selected function: FontSizeMenu
        function FontSizeMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'fontsize',app.Mainapp,"LiveSpikeRateFontSize");
            waitfor(h);

            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.LiveSpikeRateWindow.FontSize;
        end

        % Menu selected function: BarColorMenu
        function BarColorMenuSelected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.Mainapp.PlotAppearance.LiveSpikeRateWindow.BarColor = selectedColor;
            end

            app.Mainapp.PSTHApp = app;
            app.ChangeMainPlot = 0;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1;
        end

        % Menu selected function: BackgorundColorMenu
        function BackgorundColorMenuSelected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.Mainapp.PlotAppearance.LiveSpikeRateWindow.BackgroundColor = selectedColor;
                app.UIAxes.Color = app.Mainapp.PlotAppearance.LiveSpikeRateWindow.BackgroundColor;
            end

            app.Mainapp.PSTHApp = app;
            app.ChangeMainPlot = 0;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1;
        end

        % Menu selected function: XLabelMenu
        function XLabelMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'xlabel',app.Mainapp,"LiveSpikeRateXLabel");
            waitfor(h);

            app.Mainapp.PSTHApp = app;
            app.ChangeMainPlot = 0;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1;
        end

        % Menu selected function: YLabelMenu
        function YLabelMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'xlabel',app.Mainapp,"LiveSpikeRateYLabel");
            waitfor(h);

            app.Mainapp.PSTHApp = app;
            app.ChangeMainPlot = 0;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1;
        end

        % Menu selected function: RESETMenu
        function RESETMenuSelected(app, event)
            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";

            [app.Mainapp.PlotAppearance] = Organize_Set_Standard_PlotAppearance("LiveSpikeRatePlot",app.Mainapp.PlotAppearance);

            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.LiveSpikeRateWindow.FontSize;
            app.UIAxes.Color = app.Mainapp.PlotAppearance.LiveSpikeRateWindow.BackgroundColor;

            app.Mainapp.PSTHApp = app;
            app.ChangeMainPlot = 0;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1;

            %app = Utility_Change_Light_Dark_Mode(app,"LiveSpikeRatePowerWindow");
            %app.UIAxes.Box = "off";
        end

        % Menu selected function: ChangeSliderLimitsMenu
        function ChangeSliderLimitsMenuSelected(app, event)
            Channel_Spacing_Selection(app);
        end

        % Menu selected function: SaveCustomeAppearanceMenu
        function SaveCustomeAppearanceMenuSelected(app, event)
            app.Mainapp.PlotAppearance = Utility_Save_Load_Delete_Plot_Appearance(app.Mainapp.PlotAppearance,app.Mainapp.executableFolder,"Save");
        end

        % Menu selected function: DeleteCustomeAppearanceMenu
        function DeleteCustomeAppearanceMenuSelected(app, event)
            app.Mainapp.PlotAppearance = Utility_Save_Load_Delete_Plot_Appearance(app.Mainapp.PlotAppearance,app.Mainapp.executableFolder,"Delete");
        end

        % Menu selected function: xlsxMenu
        function xlsxMenuSelected(app, event)
            TimeRangePlot = str2double(app.Mainapp.TimeRangeViewBox.Value(1:end-1));
            if strcmp(app.Mainapp.DropDown.Value,"Preprocessed Data")
                if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                else
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                end
            else
                StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
            end

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".xlsx",app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Live_Spike_Rate_Main_Window",0);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            Utility_Open_UIAxes_as_Figure(app.Mainapp.Data,app.UIAxes,"LiveSpikeRate");
        end

        % Value changed function: CoupleTimetoMainWindowCheckBox
        function CoupleTimetoMainWindowCheckBoxValueChanged(app, event)
            

            value = app.CoupleTimetoMainWindowCheckBox.Value;

            if value
                title(app.UIAxes,strcat("Spike Rate of Main Window Time Range"));
            else
                selectedtime = str2double(strsplit(app.TimeWindowfromtoinsEditField.Value,','));
                title(app.UIAxes,strcat("Spike Rate in Time Range: ",num2str(selectedtime(1))," to ",num2str(selectedtime(end)),"s"));
            end

            if value
                if strcmp(app.Mainapp.PlotAppearance.MainWindow.Data.Plottype, "Surf") || strcmp(app.Mainapp.PlotAppearance.MainWindow.Data.Plottype,"Mesh")
                    app.CoupleTimetoMainWindowCheckBox.Value = 0;
                    disp("Disabling couple to main window since just one data point is plotted there in grid activity view mode!");
                    return;
                end
                if strcmp(app.Mainapp.PlotAppearance.MainWindow.Data.Plottype, "AxonViewer")
                    app.CoupleTimetoMainWindowCheckBox.Value = 0;
                    disp("Disabling couple to main window since just one data point is plotted there in axon viewer view mode!")
                    return;
                end
                app.TimeWindowfromtoinsEditField.Enable = "off";
            else
                app.TimeWindowfromtoinsEditField.Enable = "on";
                if isempty(app.TimeWindowfromtoinsEditField.Value)

                    TimeRangePlot = str2double(app.Mainapp.TimeRangeViewBox.Value(1:end-1));
                    if strcmp(app.Mainapp.DropDown.Value,"Preprocessed Data")
                        if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                            StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                        else
                            StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                        end
                    else
                        StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                    end

                    app.TimeWindowfromtoinsEditField.Value = strcat(num2str(StartTime),",",num2str(StartTime+TimeRangePlot));
                end
            end
            app.Mainapp.PSTHApp = app;
            app.ChangeMainPlot = 0;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1;
        end

        % Value changed function: TimeWindowfromtoinsEditField
        function TimeWindowfromtoinsEditFieldValueChanged(app, event)
            value = app.TimeWindowfromtoinsEditField.Value;
            if app.CoupleTimetoMainWindowCheckBox.Value
                title(app.UIAxes,strcat("Spike Rate of Main Window Time Range"));
            else
                selectedtime = str2double(strsplit(app.TimeWindowfromtoinsEditField.Value,','));
                title(app.UIAxes,strcat("Spike Rate in Time Range: ",num2str(selectedtime(1))," to ",num2str(selectedtime(end)),"s"));
            end

            app.Mainapp.PSTHApp = app;
            app.ChangeMainPlot = 0;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            app.ChangeMainPlot = 1;
        end

        % Menu selected function: SVGMenu
        function SVGMenuSelected(app, event)
            [file, path] = uiputfile('*.svg', 'Save as');
            
            exportgraphics(app.PowerEstimateWindowUIFigure, fullfile(path,file), 'ContentType', 'vector');
        end

        % Menu selected function: EPSMenu
        function EPSMenuSelected(app, event)
            [file, path] = uiputfile('*.eps', 'Save as');

            exportgraphics(app.UIAxes, fullfile(path,file), 'ContentType', 'vector')
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SpikeRateWindowUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {299, 299};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {211, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpikeRateWindowUIFigure = uifigure('Visible', 'off');
            app.SpikeRateWindowUIFigure.Name = 'Live Spike Rate Window';
            app.SpikeRateWindowUIFigure.Icon = 'Logo.png';
            app.SpikeRateWindowUIFigure.CloseRequestFcn = createCallbackFcn(app, @SpikeRateWindowUIFigureCloseRequest, true);
            app.SpikeRateWindowUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.SnapshotMenu = uimenu(app.SpikeRateWindowUIFigure);
            app.SnapshotMenu.Text = '   Snapshot   ';
            app.SaveSnapshotofWindowMenu = uimenu(app.SnapshotMenu);
            app.SaveSnapshotofWindowMenu.Text = 'Save Snapshot of Window';
            app.PNGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.PNGMenu.MenuSelectedFcn = createCallbackFcn(app, @PNGMenuSelected, true);
            app.PNGMenu.Text = 'PNG';
            app.JPGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.JPGMenu.MenuSelectedFcn = createCallbackFcn(app, @JPGMenuSelected, true);
            app.JPGMenu.Text = 'JPG';
            app.SVGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.SVGMenu.MenuSelectedFcn = createCallbackFcn(app, @SVGMenuSelected, true);
            app.SVGMenu.Text = 'SVG';
            app.EPSMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.EPSMenu.MenuSelectedFcn = createCallbackFcn(app, @EPSMenuSelected, true);
            app.EPSMenu.Text = 'EPS';
            app.PlotMenu = uimenu(app.SpikeRateWindowUIFigure);
            app.PlotMenu.Text = '   Plot   ';
            app.ChangePlotPropertiesMenu = uimenu(app.PlotMenu);
            app.ChangePlotPropertiesMenu.Text = 'Change Plot Properties';
            app.SpikeRatePlotMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.SpikeRatePlotMenu.Text = 'Spike Rate Plot';
            app.FontSizeMenu = uimenu(app.SpikeRatePlotMenu);
            app.FontSizeMenu.MenuSelectedFcn = createCallbackFcn(app, @FontSizeMenuSelected, true);
            app.FontSizeMenu.Text = 'FontSize';
            app.BarColorMenu = uimenu(app.SpikeRatePlotMenu);
            app.BarColorMenu.MenuSelectedFcn = createCallbackFcn(app, @BarColorMenuSelected, true);
            app.BarColorMenu.Text = 'Bar Color';
            app.BackgorundColorMenu = uimenu(app.SpikeRatePlotMenu);
            app.BackgorundColorMenu.MenuSelectedFcn = createCallbackFcn(app, @BackgorundColorMenuSelected, true);
            app.BackgorundColorMenu.Text = 'Backgorund Color';
            app.XLabelMenu = uimenu(app.SpikeRatePlotMenu);
            app.XLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @XLabelMenuSelected, true);
            app.XLabelMenu.Text = 'XLabel';
            app.YLabelMenu = uimenu(app.SpikeRatePlotMenu);
            app.YLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @YLabelMenuSelected, true);
            app.YLabelMenu.Text = 'YLabel';
            app.RESETMenu = uimenu(app.SpikeRatePlotMenu);
            app.RESETMenu.MenuSelectedFcn = createCallbackFcn(app, @RESETMenuSelected, true);
            app.RESETMenu.Text = 'RESET';
            app.SaveCustomeAppearanceMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.SaveCustomeAppearanceMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveCustomeAppearanceMenuSelected, true);
            app.SaveCustomeAppearanceMenu.Text = 'Save Custome Appearance';
            app.DeleteCustomeAppearanceMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.DeleteCustomeAppearanceMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteCustomeAppearanceMenuSelected, true);
            app.DeleteCustomeAppearanceMenu.Text = 'Delete Custome Appearance';
            app.ExportDataMenu = uimenu(app.SpikeRateWindowUIFigure);
            app.ExportDataMenu.Text = '   Export Data   ';
            app.matMenu = uimenu(app.ExportDataMenu);
            app.matMenu.MenuSelectedFcn = createCallbackFcn(app, @matMenuSelected, true);
            app.matMenu.Text = '.mat';
            app.txtMenu = uimenu(app.ExportDataMenu);
            app.txtMenu.MenuSelectedFcn = createCallbackFcn(app, @txtMenuSelected, true);
            app.txtMenu.Text = '.txt';
            app.xlsxMenu = uimenu(app.ExportDataMenu);
            app.xlsxMenu.MenuSelectedFcn = createCallbackFcn(app, @xlsxMenuSelected, true);
            app.xlsxMenu.Text = '.xlsx';
            app.GridLayout = uigridlayout(app.SpikeRateWindowUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SpikeRateOptionsPanel = uipanel(app.LeftPanel);
            app.SpikeRateOptionsPanel.Title = 'Spike Rate Options';
            app.DownsampleCheckBox = uicheckbox(app.SpikeRateOptionsPanel);
            app.DownsampleCheckBox.ValueChangedFcn = createCallbackFcn(app, @DownsampleCheckBoxValueChanged, true);
            app.DownsampleCheckBox.Text = '  Low Pass Filter';
            app.DownsampleCheckBox.Value = true;
            app.LockYLimCheckBox = uicheckbox(app.SpikeRateOptionsPanel);
            app.LockYLimCheckBox.ValueChangedFcn = createCallbackFcn(app, @LockYLimCheckBoxValueChanged, true);
            app.LockYLimCheckBox.Text = '   Lock YLim';
            app.LockYLimCheckBox.Value = true;
            app.SpikeRateOptionsLabel = uilabel(app.SpikeRateOptionsPanel);
            app.SpikeRateOptionsLabel.Text = 'Spike Rate Options';
            app.NrofBinsLabel = uilabel(app.SpikeRateOptionsPanel);
            app.NrofBinsLabel.Text = 'Nr of Bins';
            app.Slider = uislider(app.SpikeRateOptionsPanel);
            app.Slider.Limits = [10 90];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 30;
            app.TimeWindowfromtoinsEditFieldLabel = uilabel(app.SpikeRateOptionsPanel);
            app.TimeWindowfromtoinsEditFieldLabel.Text = {'Analysis Time'; 'Range  [from, to] in s'};
            app.TimeWindowfromtoinsEditField = uieditfield(app.SpikeRateOptionsPanel, 'text');
            app.TimeWindowfromtoinsEditField.ValueChangedFcn = createCallbackFcn(app, @TimeWindowfromtoinsEditFieldValueChanged, true);
            app.TimeWindowfromtoinsEditField.Enable = 'off';
            app.CoupleTimetoMainWindowCheckBox = uicheckbox(app.SpikeRateOptionsPanel);
            app.CoupleTimetoMainWindowCheckBox.ValueChangedFcn = createCallbackFcn(app, @CoupleTimetoMainWindowCheckBoxValueChanged, true);
            app.CoupleTimetoMainWindowCheckBox.Text = {'  Couple Time'; '  to Main Window'};
            app.CoupleTimetoMainWindowCheckBox.Value = true;
            app.CloseButton = uibutton(app.LeftPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.Button = uibutton(app.LeftPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'Open_Figure_Icon.png';
            app.Button.Text = '';
            app.ChannelSelectionPreprocessedChannelLabel = uilabel(app.LeftPanel);
            app.ChannelSelectionPreprocessedChannelLabel.Enable = 'off';
            app.ChannelSelectionPreprocessedChannelLabel.Visible = 'off';
            app.ChannelSelectionPreprocessedChannelLabel.Text = {'Channel Selection'; '(Preprocessed Channel)'};
            app.ChannelSelectionPreprocessedChannelEditField = uieditfield(app.LeftPanel, 'text');
            app.ChannelSelectionPreprocessedChannelEditField.ValueChangedFcn = createCallbackFcn(app, @ChannelSelectionPreprocessedChannelEditFieldValueChanged, true);
            app.ChannelSelectionPreprocessedChannelEditField.Enable = 'off';
            app.ChannelSelectionPreprocessedChannelEditField.Visible = 'off';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            zlabel(app.UIAxes, 'Z')
            app.ContextMenu4 = uicontextmenu(app.SpikeRateWindowUIFigure);
            app.ChangeCutoffFrequencyMenu = uimenu(app.ContextMenu4);
            app.ChangeCutoffFrequencyMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeCutoffFrequencyMenuSelected, true);
            app.ChangeCutoffFrequencyMenu.Text = 'Change Cutoff Frequency';
            app.ChangeFilterOrderMenu = uimenu(app.ContextMenu4);
            app.ChangeFilterOrderMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeFilterOrderMenuSelected, true);
            app.ChangeFilterOrderMenu.Text = 'Change Filter Order';
            % Assign app.ContextMenu4
            app.DownsampleCheckBox.ContextMenu = app.ContextMenu4;
            app.ContextMenu5 = uicontextmenu(app.SpikeRateWindowUIFigure);
            app.ChangeSliderLimitsMenu = uimenu(app.ContextMenu5);
            app.ChangeSliderLimitsMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeSliderLimitsMenuSelected, true);
            app.ChangeSliderLimitsMenu.Text = 'Change Slider Limits';
            % Assign app.ContextMenu5
            app.Slider.ContextMenu = app.ContextMenu5;
            % Show the figure after all components are created
            app.SpikeRateWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Spike_Rate_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SpikeRateWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.SpikeRateWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpikeRateWindowUIFigure)
        end
    end
end