classdef ASR_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ASRWindowUIFigure            matlab.ui.Figure
        AddASRtoPipelineButton       matlab.ui.control.Button
        ArtefactSubspaceReconstructionPanel  matlab.ui.container.Panel
        ChannelCriterion01EditField  matlab.ui.control.EditField
        ChannelCriterion01Label      matlab.ui.control.Label
        LineNoiseCriterioninStandardDevationsEditField  matlab.ui.control.EditField
        LineNoiseCriterionLabel      matlab.ui.control.Label
        WindowCriterionRange00503EditField  matlab.ui.control.EditField
        WindowCriterionRange00503EditFieldLabel  matlab.ui.control.Label
        BurstCriterioninStandardDevationsRange520EditField  matlab.ui.control.EditField
        BurstCriterioninstandarddevationsLabel  matlab.ui.control.Label
        HighPassTransitionBandLTHTEditField  matlab.ui.control.EditField
        HighPassTransitionBandLTHTEditFieldLabel  matlab.ui.control.Label
        EnableCheckBox_4             matlab.ui.control.CheckBox
        EnableCheckBox_3             matlab.ui.control.CheckBox
        EnableCheckBox_2             matlab.ui.control.CheckBox
        EnableCheckBox               matlab.ui.control.CheckBox
        ArtefactSubspaceReconstructionLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        Mainapp = []; % Description
        PreproApp = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp, PreproApp)
            %% Initiate GUI 
            movegui(app.ASRWindowUIFigure, 'center');

            app.Mainapp = Mainapp;
            app.PreproApp = PreproApp;

            app = Utility_Change_Light_Dark_Mode(app,"ASR_Window");
        end

        % Button pushed function: AddASRtoPipelineButton
        function AddASRtoPipelineButtonPushed(app, event)
            
            app.PreproApp.Info.ASRChannelCriterion = str2double(app.ChannelCriterion01EditField.Value);
            app.PreproApp.Info.ASRLineNoiseC = str2double(app.LineNoiseCriterioninStandardDevationsEditField.Value);
            app.PreproApp.Info.ASRHPTransitions = str2double(strsplit(app.HighPassTransitionBandLTHTEditField.Value,','));
            app.PreproApp.Info.ASRBurstC = str2double(app.BurstCriterioninStandardDevationsRange520EditField.Value);
            app.PreproApp.Info.WindowC = str2double(app.WindowCriterionRange00503EditField.Value);
            
            app.PreproApp.Info.EnableChannelCrit = app.EnableCheckBox_3.Value;
            app.PreproApp.Info.EnableLineNoiseCrit = app.EnableCheckBox_4.Value;
            app.PreproApp.Info.EnableBurstCrit = app.EnableCheckBox_2.Value;
            app.PreproApp.Info.EnableWindowCrit = app.EnableCheckBox.Value;

            [app.PreproApp.Info,app.PreproApp.PreprocessingSteps,InfoText] = Preprocess_Module_Construct_Pipeline("ASR",app.PreproApp.Info,app.PreproApp.PreprocessingSteps,0,app.PreproApp.FilterMethodDropDown.Value,app.PreproApp.FilterTypeDropDown.Value,app.PreproApp.CuttoffFrequencyHzEditField.Value,app.PreproApp.FilterDirectionDropDown.Value,app.PreproApp.FilterOrderEditField.Value,app.PreproApp.DownsampleFactorEditField.Value,app.Mainapp.Data.Info.NativeSamplingRate);
            %[~,app.Mainapp.Data] = Utility_Translate_Into_EEGLAB_struc(app.Mainapp.Data,LineNoiseCriterion,HPTransitions,BurstCriterion,WindowCriterion);
            
            if ~isempty(InfoText)
                app.PreproApp.TextArea.Value = InfoText;
            end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ASRWindowUIFigure = uifigure('Visible', 'off');
            app.ASRWindowUIFigure.Name = 'ASR Window';
            app.ASRWindowUIFigure.Icon = 'Logo.png';
            app.ArtefactSubspaceReconstructionPanel = uipanel(app.ASRWindowUIFigure);
            app.ArtefactSubspaceReconstructionPanel.Title = 'Artefact Subspace Reconstruction';
            app.ArtefactSubspaceReconstructionLabel = uilabel(app.ArtefactSubspaceReconstructionPanel);
            app.ArtefactSubspaceReconstructionLabel.Text = 'Artefact Subspace Reconstruction';
            app.EnableCheckBox = uicheckbox(app.ArtefactSubspaceReconstructionPanel);
            app.EnableCheckBox.Visible = 'off';
            app.EnableCheckBox.Enable = 'off';
            app.EnableCheckBox.Text = '   Enable ';
            app.EnableCheckBox_2 = uicheckbox(app.ArtefactSubspaceReconstructionPanel);
            app.EnableCheckBox_2.Text = '   Enable ';
            app.EnableCheckBox_2.Value = true;
            app.EnableCheckBox_3 = uicheckbox(app.ArtefactSubspaceReconstructionPanel);
            app.EnableCheckBox_3.Text = '   Enable ';
            app.EnableCheckBox_3.Value = true;
            app.EnableCheckBox_4 = uicheckbox(app.ArtefactSubspaceReconstructionPanel);
            app.EnableCheckBox_4.Text = '   Enable ';
            app.EnableCheckBox_4.Value = true;
            app.HighPassTransitionBandLTHTEditFieldLabel = uilabel(app.ArtefactSubspaceReconstructionPanel);
            app.HighPassTransitionBandLTHTEditFieldLabel.Text = {'High Pass Transition Band'; '[LT,HT]'};
            app.HighPassTransitionBandLTHTEditField = uieditfield(app.ArtefactSubspaceReconstructionPanel, 'text');
            app.HighPassTransitionBandLTHTEditField.Value = '0.25,0.75';
            app.BurstCriterioninstandarddevationsLabel = uilabel(app.ArtefactSubspaceReconstructionPanel);
            app.BurstCriterioninstandarddevationsLabel.Text = {'Burst Criterion (in Standard '; 'Devations,) Range 5-20'};
            app.BurstCriterioninStandardDevationsRange520EditField = uieditfield(app.ArtefactSubspaceReconstructionPanel, 'text');
            app.BurstCriterioninStandardDevationsRange520EditField.Value = '5';
            app.WindowCriterionRange00503EditFieldLabel = uilabel(app.ArtefactSubspaceReconstructionPanel);
            app.WindowCriterionRange00503EditFieldLabel.Enable = 'off';
            app.WindowCriterionRange00503EditFieldLabel.Visible = 'off';
            app.WindowCriterionRange00503EditFieldLabel.Text = {'Window Criterion'; 'Range 0.05 - 0.3'};
            app.WindowCriterionRange00503EditField = uieditfield(app.ArtefactSubspaceReconstructionPanel, 'text');
            app.WindowCriterionRange00503EditField.Editable = 'off';
            app.WindowCriterionRange00503EditField.Enable = 'off';
            app.WindowCriterionRange00503EditField.Visible = 'off';
            app.WindowCriterionRange00503EditField.Value = '0.25';
            app.LineNoiseCriterionLabel = uilabel(app.ArtefactSubspaceReconstructionPanel);
            app.LineNoiseCriterionLabel.Text = {'Line Noise Criterion'; '(in Standard Devations)'};
            app.LineNoiseCriterioninStandardDevationsEditField = uieditfield(app.ArtefactSubspaceReconstructionPanel, 'text');
            app.LineNoiseCriterioninStandardDevationsEditField.Value = '4';
            app.ChannelCriterion01Label = uilabel(app.ArtefactSubspaceReconstructionPanel);
            app.ChannelCriterion01Label.Text = 'Channel Criterion (0-1)';
            app.ChannelCriterion01EditField = uieditfield(app.ArtefactSubspaceReconstructionPanel, 'text');
            app.ChannelCriterion01EditField.Value = '0.85';
            app.AddASRtoPipelineButton = uibutton(app.ASRWindowUIFigure, 'push');
            app.AddASRtoPipelineButton.ButtonPushedFcn = createCallbackFcn(app, @AddASRtoPipelineButtonPushed, true);
            app.AddASRtoPipelineButton.Text = 'Add ASR to Pipeline';
            % Show the figure after all components are created
            app.ASRWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ASR_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ASRWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ASRWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ASRWindowUIFigure)
        end
    end
end