classdef Artefact_Rejection_Continous_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ContinousArtefactRejectionUIFigure  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        RightPanel                      matlab.ui.container.Panel
        RejectionMethodDropDown         matlab.ui.control.DropDown
        RejectionMethodDropDownLabel    matlab.ui.control.Label
        RejectionOptionsLabel           matlab.ui.control.Label
        CloseButton                     matlab.ui.control.Button
        InformationTextArea             matlab.ui.control.TextArea
        InformationTextAreaLabel        matlab.ui.control.Label
        RejectionOptionsPanel           matlab.ui.container.Panel
        TimeAroundEventsEditField_3     matlab.ui.control.EditField
        ArtefactTimeRangesLabel_2       matlab.ui.control.Label
        TimeAroundEventsEditField_2     matlab.ui.control.EditField
        ArtefactTimeRangesLabel         matlab.ui.control.Label
        EventstoPlotDropDown            matlab.ui.control.DropDown
        EventstoPlotLabel               matlab.ui.control.Label
        AddArtefactRejectiontoPipelineButton  matlab.ui.control.Button
        TimeAroundEventsEditField       matlab.ui.control.EditField
        TimetoPlotAroundEventsLabel     matlab.ui.control.Label
        EventChannelforStimulationDropDown  matlab.ui.control.DropDown
        EventChannelofStimulationLabel  matlab.ui.control.Label
        Slider                          matlab.ui.control.Slider
        DataLineSpacingSliderLabel      matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        ContextMenu                     matlab.ui.container.ContextMenu
        ChangeChannelSpacingLimitsMenu  matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        PreproApp = [] % Description
        PlotTimeVector = [];
        StimArtefactInfo = []
        Mainapp = [];
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, PreproApp)
            %% Initiate GUI 
            movegui(app.ContinousArtefactRejectionUIFigure, 'center');
            app.PreproApp = PreproApp;

            app = Utility_Change_Light_Dark_Mode(app,"Artefact_Rejection_Window");

            app.Mainapp = PreproApp.Mainapp;

            app.Slider.Limits = app.PreproApp.Mainapp.Slider.Limits;
            app.Slider.Value = app.PreproApp.Mainapp.Slider.Value;
            
            for neventchannel = 1:length(app.PreproApp.Mainapp.Data.Events)
                app.EventChannelforStimulationDropDown.Items{neventchannel} = app.PreproApp.Mainapp.Data.Info.EventChannelNames{neventchannel};
            end

            app.EventstoPlotDropDown.Items{1} = 'Mean over all Trigger';

            for i = 1:length(app.PreproApp.Mainapp.Data.Events{1})
                app.EventstoPlotDropDown.Items{i+1} = num2str(i);
            end

            %% Show Info in text area

            SelectedEventIndicie = [];
            for neventchannel = 1:length(app.EventChannelforStimulationDropDown.Items)
                if strcmp(app.EventChannelforStimulationDropDown.Value,app.PreproApp.Mainapp.Data.Info.EventChannelNames{neventchannel})
                    SelectedEventIndicie = neventchannel;
                end
            end

            Texttoshow = ["Select the event channel containing time points of your stimulation trigger and specify the desired time range around artefacts. Values in that range get interpolated. 'Events to Plot' dropdown menu is only for plotting purposes! Interpolation is applied to all events specified and all channel."];
            Texttoshow = [Texttoshow;"";"Selected event channel for getting stimuls artefact time points: ";"";app.EventChannelforStimulationDropDown.Value];
            Texttoshow = [Texttoshow;strcat("Number of triggers in that channel: ",num2str(length(app.PreproApp.Mainapp.Data.Events{SelectedEventIndicie})))];

            app.InformationTextArea.Value = Texttoshow;

            %% Extract and plot stim artefacts

            [~,app.StimArtefactInfo] = Preprocess_Extract_and_Plot_Stimulation_Artefact(app.PreproApp.Mainapp.Data,app.TimeAroundEventsEditField_2.Value,app.TimeAroundEventsEditField.Value,app.EventChannelforStimulationDropDown.Value,app.EventstoPlotDropDown.Value,app.Slider.Value,app.UIAxes,app.PreproApp.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations);            
             
        end

        % Callback function
        function ArtefactRejectionusingEventsButtonPushed(app, event)


        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            try
                app.PreproApp.Mainapp.PreproArtefactRejection = [];
    
                if ~isempty(app.PreproApp.Mainapp.ProbeViewWindowHandle) % delete option from probe view 
                    for i = 1:length(app.PreproApp.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                        if strcmp(app.PreproApp.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Prepro Artefact Rejection')
                            app.PreproApp.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items(i) = [];
                            break;
                        end
                    end
                else % if no probe view available
     
                end
            end

            delete(app);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            
            %% Extract and plot stim artefacts

            [~,app.StimArtefactInfo] = Preprocess_Extract_and_Plot_Stimulation_Artefact(app.PreproApp.Mainapp.Data,app.TimeAroundEventsEditField_2.Value,app.TimeAroundEventsEditField.Value,app.EventChannelforStimulationDropDown.Value,app.EventstoPlotDropDown.Value,app.Slider.Value,app.UIAxes,app.PreproApp.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations);            
            
        end

        % Value changed function: EventstoPlotDropDown
        function EventstoPlotDropDownValueChanged(app, event)
            
            %% Extract and plot stim artefacts

            [~,app.StimArtefactInfo] = Preprocess_Extract_and_Plot_Stimulation_Artefact(app.PreproApp.Mainapp.Data,app.TimeAroundEventsEditField_2.Value,app.TimeAroundEventsEditField.Value,app.EventChannelforStimulationDropDown.Value,app.EventstoPlotDropDown.Value,app.Slider.Value,app.UIAxes,app.PreproApp.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations);            
            
        end

        % Value changed function: TimeAroundEventsEditField
        function TimeAroundEventsEditFieldValueChanged(app, event)
            [app.TimeAroundEventsEditField.Value] = Utility_SimpleCheckInputs(app.TimeAroundEventsEditField.Value,"Two",'-0.2,0.2',0,1);
            %% Extract and plot stim artefacts

            [~,app.StimArtefactInfo] = Preprocess_Extract_and_Plot_Stimulation_Artefact(app.PreproApp.Mainapp.Data,app.TimeAroundEventsEditField_2.Value,app.TimeAroundEventsEditField.Value,app.EventChannelforStimulationDropDown.Value,app.EventstoPlotDropDown.Value,app.Slider.Value,app.UIAxes,app.PreproApp.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations);            
            
        end

        % Value changed function: EventChannelforStimulationDropDown
        function EventChannelforStimulationDropDownValueChanged(app, event)
            value = app.EventChannelforStimulationDropDown.Value;
            %% Extract and plot stim artefacts

            [~,app.StimArtefactInfo] = Preprocess_Extract_and_Plot_Stimulation_Artefact(app.PreproApp.Mainapp.Data,app.TimeAroundEventsEditField_2.Value,app.TimeAroundEventsEditField.Value,app.EventChannelforStimulationDropDown.Value,app.EventstoPlotDropDown.Value,app.Slider.Value,app.UIAxes,app.PreproApp.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations);            
            
            %% Show Info in text area

            SelectedEventIndicie = [];
            for neventchannel = 1:length(app.EventChannelforStimulationDropDown.Items)
                if strcmp(app.EventChannelforStimulationDropDown.Value,app.PreproApp.Mainapp.Data.Info.EventChannelNames{neventchannel})
                    SelectedEventIndicie = neventchannel;
                end
            end

            Texttoshow = ["Select the event channel containing time points of your stimulation and specify the desired time range around artefacts. Values in that range get interpolated. 'Events to Plot' dropdown menu is only for plotting purposes! Interpolation is applied to all events and channel."];
            Texttoshow = [Texttoshow;"";"Selected event channel for getting stimuls artefact time points: ";"";app.EventChannelforStimulationDropDown.Value];
            Texttoshow = [Texttoshow;strcat("Number of triggers in that channel: ",num2str(length(app.PreproApp.Mainapp.Data.Events{SelectedEventIndicie})))];

            app.InformationTextArea.Value = Texttoshow;
        end

        % Button pushed function: AddArtefactRejectiontoPipelineButton
        function AddArtefactRejectiontoPipelineButtonPushed(app, event)
            % Add Selection to the pipeline. Filterorder, cutoff and all
            %% other relevant parameters are extracted and saved here (bc multiple filter can be selected and settings have to be saved in between)!
            % Additionally checks logic if user wants to add two or more of the following
            % three filters: Low Pass, High Pass, Narrwoband. This is
            % rejected since it makes no sense. Instead, the existing added
            % filter type is replace by the most recent one added.
            
            if ~isempty(app.TimeAroundEventsEditField_3.Value)
                app.StimArtefactInfo.SelectedTriggertChannel = eval(app.TimeAroundEventsEditField_3.Value);
            else
                SelectedEventIndicie = [];
                for neventchannel = 1:length(app.EventChannelforStimulationDropDown.Items)
                    if strcmp(app.EventChannelforStimulationDropDown.Value,app.PreproApp.Mainapp.Data.Info.EventChannelNames{neventchannel})
                        SelectedEventIndicie = neventchannel;
                    end
                end
                app.StimArtefactInfo.SelectedTriggertChannel = 1:length(app.Mainapp.Data.Events{SelectedEventIndicie});
            end

            [app.StimArtefactInfo.ChannelSelection] = Organize_Convert_ActiveChannel_to_DataChannel(app.PreproApp.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.PreproApp.Mainapp.ActiveChannel,'MainWindow');
            
            [app.PreproApp.Info,app.PreproApp.PreprocessingSteps,InfoText] = Preprocess_Module_Construct_Pipeline("StimArtefactRejection",app.PreproApp.Info,app.PreproApp.PreprocessingSteps,0,app.PreproApp.FilterMethodDropDown.Value,app.PreproApp.FilterTypeDropDown.Value,app.PreproApp.CuttoffFrequencyHzEditField.Value,app.PreproApp.FilterDirectionDropDown.Value,app.PreproApp.FilterOrderEditField.Value,app.PreproApp.DownsampleFactorEditField.Value,app.PreproApp.Mainapp.Data.Info.NativeSamplingRate,app.StimArtefactInfo);
                        
            if ~isempty(InfoText)
                app.PreproApp.TextArea.Value = InfoText;
            end

            app.PreproApp.Mainapp.PreproArtefactRejection = [];

            if ~isempty(app.PreproApp.Mainapp.ProbeViewWindowHandle) % delete option from probe view 
                for i = 1:length(app.PreproApp.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                    if strcmp(app.PreproApp.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Prepro Artefact Rejection')
                        app.PreproApp.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items(i) = [];
                        break;
                    end
                end
            else % if no probe view available
 
            end

            delete(app);
        end

        % Value changed function: TimeAroundEventsEditField_2
        function TimeAroundEventsEditField_2ValueChanged(app, event)
            [app.TimeAroundEventsEditField_2.Value] = Utility_SimpleCheckInputs(app.TimeAroundEventsEditField_2.Value,"Two",'-0.05,0.05',0,1);
            %% Extract and plot stim artefacts

            [~,app.StimArtefactInfo] = Preprocess_Extract_and_Plot_Stimulation_Artefact(app.PreproApp.Mainapp.Data,app.TimeAroundEventsEditField_2.Value,app.TimeAroundEventsEditField.Value,app.EventChannelforStimulationDropDown.Value,app.EventstoPlotDropDown.Value,app.Slider.Value,app.UIAxes,app.PreproApp.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations);            
            
        end

        % Menu selected function: ChangeChannelSpacingLimitsMenu
        function ChangeChannelSpacingLimitsMenuSelected(app, event)
            Channel_Spacing_Selection(app);
        end

        % Close request function: ContinousArtefactRejectionUIFigure
        function ContinousArtefactRejectionUIFigureCloseRequest(app, event)
            try
                app.PreproApp.Mainapp.PreproArtefactRejection = [];
    
                if ~isempty(app.PreproApp.Mainapp.ProbeViewWindowHandle) % delete option from probe view 
                    for i = 1:length(app.PreproApp.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                        if strcmp(app.PreproApp.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Prepro Artefact Rejection')
                            app.PreproApp.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items(i) = [];
                            break;
                        end
                    end
                else % if no probe view available
     
                end
            end

            delete(app);
        end

        % Value changed function: TimeAroundEventsEditField_3
        function TimeAroundEventsEditField_3ValueChanged(app, event)
            value = app.TimeAroundEventsEditField_3.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ContinousArtefactRejectionUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {685, 685};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ContinousArtefactRejectionUIFigure = uifigure('Visible', 'off');
            app.ContinousArtefactRejectionUIFigure.Name = 'Continous Artefact Rejection';
            app.ContinousArtefactRejectionUIFigure.Icon = 'Logo.png';
            app.ContinousArtefactRejectionUIFigure.CloseRequestFcn = createCallbackFcn(app, @ContinousArtefactRejectionUIFigureCloseRequest, true);
            app.ContinousArtefactRejectionUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.ContinousArtefactRejectionUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.YTickLabel = '';
            app.DataLineSpacingSliderLabel = uilabel(app.RightPanel);
            app.DataLineSpacingSliderLabel.Text = {'Data Line'; 'Spacing'};
            app.Slider = uislider(app.RightPanel);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.RejectionOptionsPanel = uipanel(app.RightPanel);
            app.RejectionOptionsPanel.Title = 'Rejection Options';
            app.EventChannelofStimulationLabel = uilabel(app.RejectionOptionsPanel);
            app.EventChannelofStimulationLabel.Text = {'Event Channel of '; 'Stimulation'};
            app.EventChannelforStimulationDropDown = uidropdown(app.RejectionOptionsPanel);
            app.EventChannelforStimulationDropDown.Items = {''};
            app.EventChannelforStimulationDropDown.ValueChangedFcn = createCallbackFcn(app, @EventChannelforStimulationDropDownValueChanged, true);
            app.EventChannelforStimulationDropDown.Value = '';
            app.TimetoPlotAroundEventsLabel = uilabel(app.RejectionOptionsPanel);
            app.TimetoPlotAroundEventsLabel.Text = {'Time to Plot'; 'Around Event [s]'};
            app.TimeAroundEventsEditField = uieditfield(app.RejectionOptionsPanel, 'text');
            app.TimeAroundEventsEditField.ValueChangedFcn = createCallbackFcn(app, @TimeAroundEventsEditFieldValueChanged, true);
            app.TimeAroundEventsEditField.Value = '-0.2,0.2';
            app.AddArtefactRejectiontoPipelineButton = uibutton(app.RejectionOptionsPanel, 'push');
            app.AddArtefactRejectiontoPipelineButton.ButtonPushedFcn = createCallbackFcn(app, @AddArtefactRejectiontoPipelineButtonPushed, true);
            app.AddArtefactRejectiontoPipelineButton.Text = {'Add Artefact Rejection'; 'to Pipeline'};
            app.EventstoPlotLabel = uilabel(app.RejectionOptionsPanel);
            app.EventstoPlotLabel.Text = 'Trigger to Plot';
            app.EventstoPlotDropDown = uidropdown(app.RejectionOptionsPanel);
            app.EventstoPlotDropDown.Items = {'Mean over all Events'};
            app.EventstoPlotDropDown.ValueChangedFcn = createCallbackFcn(app, @EventstoPlotDropDownValueChanged, true);
            app.EventstoPlotDropDown.Value = 'Mean over all Events';
            app.ArtefactTimeRangesLabel = uilabel(app.RejectionOptionsPanel);
            app.ArtefactTimeRangesLabel.Text = {'Artefact Time'; 'Range [s]'};
            app.TimeAroundEventsEditField_2 = uieditfield(app.RejectionOptionsPanel, 'text');
            app.TimeAroundEventsEditField_2.ValueChangedFcn = createCallbackFcn(app, @TimeAroundEventsEditField_2ValueChanged, true);
            app.TimeAroundEventsEditField_2.Value = '-0.05,0.05';
            app.ArtefactTimeRangesLabel_2 = uilabel(app.RejectionOptionsPanel);
            app.ArtefactTimeRangesLabel_2.Text = {'Trigger to Reject (Matlab Expressions)'; '[empty = all trigger]'};
            app.TimeAroundEventsEditField_3 = uieditfield(app.RejectionOptionsPanel, 'text');
            app.TimeAroundEventsEditField_3.ValueChangedFcn = createCallbackFcn(app, @TimeAroundEventsEditField_3ValueChanged, true);
            app.InformationTextAreaLabel = uilabel(app.RightPanel);
            app.InformationTextAreaLabel.Text = 'Information';
            app.InformationTextArea = uitextarea(app.RightPanel);
            app.CloseButton = uibutton(app.RightPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.RejectionOptionsLabel = uilabel(app.RightPanel);
            app.RejectionOptionsLabel.Text = 'Rejection Options';
            app.RejectionMethodDropDownLabel = uilabel(app.RightPanel);
            app.RejectionMethodDropDownLabel.Enable = 'off';
            app.RejectionMethodDropDownLabel.Visible = 'off';
            app.RejectionMethodDropDownLabel.Text = 'Rejection Method';
            app.RejectionMethodDropDown = uidropdown(app.RightPanel);
            app.RejectionMethodDropDown.Items = {'Interpolate'};
            app.RejectionMethodDropDown.Enable = 'off';
            app.RejectionMethodDropDown.Visible = 'off';
            app.RejectionMethodDropDown.Value = 'Interpolate';
            app.ContextMenu = uicontextmenu(app.ContinousArtefactRejectionUIFigure);
            app.ChangeChannelSpacingLimitsMenu = uimenu(app.ContextMenu);
            app.ChangeChannelSpacingLimitsMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeChannelSpacingLimitsMenuSelected, true);
            app.ChangeChannelSpacingLimitsMenu.Text = 'Change Channel Spacing Limits';
            % Assign app.ContextMenu
            app.Slider.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.ContinousArtefactRejectionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Artefact_Rejection_Continous_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ContinousArtefactRejectionUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ContinousArtefactRejectionUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ContinousArtefactRejectionUIFigure)
        end
    end
end