classdef Ask_For_ASR_ChannelDeletion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ASRChannelDeletionWindowUIFigure  matlab.ui.Figure
        NoButton   matlab.ui.control.Button
        YesButton  matlab.ui.control.Button
        TextArea   matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        ChannelToDelete = [];
        DeleteChannel = 0; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ChannelToDelete)
            % Moce this window in the middle of thescreen
            movegui(app.ASRChannelDeletionWindowUIFigure, 'center');

            app.ChannelToDelete = ChannelToDelete;

            app = Utility_Change_Light_Dark_Mode(app,"ASR_Ask_Channeldeletion");

            app.TextArea.Value{1} = 'Artefact subspace reconstruction would delete recording channel. This is also applied to the raw dataset and can not be reversed! Select if you want to proceed with deleting those channel or not. If not, artefact subspace reconstruction results are not saved and you have to try again with different settings to apply it.';
            app.TextArea.Value{2} = 'Channel to be deleted: ';
            app.TextArea.Value{3} = convertStringsToChars(strjoin(string(app.ChannelToDelete),','));

           
        end

        % Close request function: ASRChannelDeletionWindowUIFigure
        function ASRChannelDeletionWindowUIFigureCloseRequest(app, event)
            
            delete(app)
            
        end

        % Button pushed function: YesButton
        function YesButtonPushed(app, event)
            app.DeleteChannel = 1;
            uiresume(app.ASRChannelDeletionWindowUIFigure);
        end

        % Button pushed function: NoButton
        function NoButtonPushed(app, event)
            app.DeleteChannel = 0;
            uiresume(app.ASRChannelDeletionWindowUIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ASRChannelDeletionWindowUIFigure = uifigure('Visible', 'off');
            app.ASRChannelDeletionWindowUIFigure.Name = 'ASR Channel Deletion Window';
            app.ASRChannelDeletionWindowUIFigure.Icon = 'Logo.png';
            app.ASRChannelDeletionWindowUIFigure.CloseRequestFcn = createCallbackFcn(app, @ASRChannelDeletionWindowUIFigureCloseRequest, true);
            app.TextArea = uitextarea(app.ASRChannelDeletionWindowUIFigure);
            app.YesButton = uibutton(app.ASRChannelDeletionWindowUIFigure, 'push');
            app.YesButton.ButtonPushedFcn = createCallbackFcn(app, @YesButtonPushed, true);
            app.YesButton.Text = 'Yes';
            app.NoButton = uibutton(app.ASRChannelDeletionWindowUIFigure, 'push');
            app.NoButton.ButtonPushedFcn = createCallbackFcn(app, @NoButtonPushed, true);
            app.NoButton.Text = 'No';
            % Show the figure after all components are created
            app.ASRChannelDeletionWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Ask_For_ASR_ChannelDeletion(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ASRChannelDeletionWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ASRChannelDeletionWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ASRChannelDeletionWindowUIFigure)
        end
    end
end