classdef Channel_Deletion_Selection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ChannelDeletionWindowUIFigure  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        RightPanel                     matlab.ui.container.Panel
        CloseButton                    matlab.ui.control.Button
        SaveSelectionandaddtopipelineButton  matlab.ui.control.Button
        ChanneltoDelete                matlab.ui.control.EditField
        ChanneltoDeleteMatlabfunctionsonlyexample110or10Label  matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        PreprocessingApp = []; % Description
        Userinput = [];
        Mainapp = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, PreprocessingApp)
            movegui(app.ChannelDeletionWindowUIFigure, 'center');
            
            app.Mainapp = PreprocessingApp.Mainapp;

            app = Utility_Change_Light_Dark_Mode(app,"DeleteChannel");

            app.PreprocessingApp = PreprocessingApp;
        end

        % Value changed function: ChanneltoDelete
        function ChanneltoDeleteValueChanged(app, event)
            value = app.ChanneltoDelete.Value;
            
            try
                if ~contains(app.ChanneltoDelete.Value,'[')
                    app.ChanneltoDelete.Value = strcat('[',app.ChanneltoDelete.Value);
                end
                if ~contains(app.ChanneltoDelete.Value,']')
                    app.ChanneltoDelete.Value = strcat(app.ChanneltoDelete.Value,']');
                end
                app.Userinput = eval(app.ChanneltoDelete.Value);
            catch
                msgbox("Error: Could not convert into variable! Please check syntax.")
                app.ChanneltoDelete.Value = '';
                return;
            end

            if sum(ismember(app.Userinput,app.Mainapp.Data.Info.ProbeInfo.ActiveChannel)) ~= length(app.Userinput)
                msgbox("Error: One or more of the selected channel are not currently part of the active channel probes. Check the 'Probe View Window' to see all active channel!")
                %app.ChanneltoDelete.Value = '';
                app.Userinput = [];
                return
            end

            app.Userinput = eval(app.ChanneltoDelete.Value);

            if sum(ismember(app.Userinput,app.Mainapp.Data.Info.ProbeInfo.ActiveChannel)) ~= length(app.Userinput)
                msgbox("Not all specified channel are part of the defined active channel of the probe design!")
                app.Userinput = [];
                return;
            end
            
        end

        % Button pushed function: SaveSelectionandaddtopipelineButton
        function SaveSelectionandaddtopipelineButtonPushed(app, event)
            if isempty(app.ChanneltoDelete.Value)
                msgbox("Error: No channel selected.")
                return;
            end

            if isempty(app.Userinput)
                msgbox("Error: No valid channel selected.")
                return;
            end
            
            try
                Input = eval(app.ChanneltoDelete.Value);
            catch
                msgbox("Error: Could not convert into variable! Please check syntax.")
                app.ChanneltoDelete.Value = '';
                return;
            end
            
            if ~isa(Input,'double') && ~isa(Input,'single')
                msgbox("Error: Only numbers allowed!");
                return;
            end
            
            if Input(1) > Input(end) || Input(1) <= 0
                msgbox("Format error! Only positive numbers from smaller to bigger allowed.");
                return;
            end

            if all(ismember(Input, app.Mainapp.Data.Info.ProbeInfo.ActiveChannel)) == 0
                msgbox("Error: One or more of the selected channel are not currently part of the active channel probes. Check the 'Probe View Window' to see all active channel!")
                return;
            end
            
            if isempty(app.Userinput)
                msgbox("No channel specified and added to the pipeline!")
                delete(app);
                return
            end

            app.PreprocessingApp.Info.ChannelDeletion = app.Userinput;

            %% Add Selection to the pipeline. Filterorder, cutoff and all
            %% other relevant parameters are extracted and saved here (bc multiple filter can be selected and settings have to be saved in between)!
            % Additionally checks logic if user wants to add two or more of the following
            % three filters: Low Pass, High Pass, Narrwoband. This is
            % rejected since it makes no sense. Instead, the existing added
            % filter type is replace by the most recent one added.
            [app.PreprocessingApp.Info,app.PreprocessingApp.PreprocessingSteps,texttoshow] = Preprocess_Module_Construct_Pipeline("ChannelDeletion",app.PreprocessingApp.Info,app.PreprocessingApp.PreprocessingSteps,0,[],[],[],[],[],[],app.PreprocessingApp.Mainapp.Data.Info.NativeSamplingRate);
            
            if ~isempty(texttoshow)
                app.PreprocessingApp.TextArea.Value = texttoshow;
            end
            
            delete(app);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ChannelDeletionWindowUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {188, 188};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ChannelDeletionWindowUIFigure = uifigure('Visible', 'off');
            app.ChannelDeletionWindowUIFigure.Name = 'Channel Deletion Window';
            app.ChannelDeletionWindowUIFigure.Icon = 'Logo.png';
            app.ChannelDeletionWindowUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.ChannelDeletionWindowUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.ChanneltoDeleteMatlabfunctionsonlyexample110or10Label = uilabel(app.RightPanel);
            app.ChanneltoDeleteMatlabfunctionsonlyexample110or10Label.Text = {'Channel to Delete (Only Active Channel!)'; '(Matlab functions only)'; 'example.: 1:10 or [1,5,10]'};
            app.ChanneltoDelete = uieditfield(app.RightPanel, 'text');
            app.ChanneltoDelete.ValueChangedFcn = createCallbackFcn(app, @ChanneltoDeleteValueChanged, true);
            app.SaveSelectionandaddtopipelineButton = uibutton(app.RightPanel, 'push');
            app.SaveSelectionandaddtopipelineButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSelectionandaddtopipelineButtonPushed, true);
            app.SaveSelectionandaddtopipelineButton.Text = {'Save Selection and '; 'add to pipeline'};
            app.CloseButton = uibutton(app.RightPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.ChannelDeletionWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Channel_Deletion_Selection(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ChannelDeletionWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ChannelDeletionWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ChannelDeletionWindowUIFigure)
        end
    end
end