classdef Preprocessing_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PreprocessingWindowUIFigure    matlab.ui.Figure
        FilterDirectionDropDown        matlab.ui.control.DropDown
        FilterDirectionDropDownLabel   matlab.ui.control.Label
        FilteringLabel                 matlab.ui.control.Label
        FilteringPanel                 matlab.ui.container.Panel
        InspectFilterButton            matlab.ui.control.Button
        FilterOrderEditField           matlab.ui.control.EditField
        FilterOrderEditFieldLabel      matlab.ui.control.Label
        CuttoffFrequencyHzEditField    matlab.ui.control.EditField
        CuttoffFrequencyHzLabel        matlab.ui.control.Label
        FilterTypeDropDown             matlab.ui.control.DropDown
        FilterTypeDropDownLabel        matlab.ui.control.Label
        FilterMethodDropDown           matlab.ui.control.DropDown
        FilterMethodDropDownLabel      matlab.ui.control.Label
        AddtoPipelineButton            matlab.ui.control.Button
        PlotExampleButton              matlab.ui.control.Button
        NormalizeLabel                 matlab.ui.control.Label
        DownsamplingLabel              matlab.ui.control.Label
        NormalizePanel                 matlab.ui.container.Panel
        AddtoPipelineButton_3          matlab.ui.control.Button
        PlotExampleButton_4            matlab.ui.control.Button
        DownsamplingPanel              matlab.ui.container.Panel
        DownsampleFactorEditField      matlab.ui.control.EditField
        DownsampleFactorLabel          matlab.ui.control.Label
        AddtoPipelineButton_2          matlab.ui.control.Button
        PlotExampleButton_2            matlab.ui.control.Button
        CloseButton                    matlab.ui.control.Button
        GrandAverageLabel              matlab.ui.control.Label
        DeleteLastPipelineEntryButton  matlab.ui.control.Button
        StartPipelineButton            matlab.ui.control.Button
        PipelineComponentsLabel        matlab.ui.control.Label
        TextArea                       matlab.ui.control.TextArea
        GrandAveragePanel              matlab.ui.container.Panel
        AddtoPipelineButton_4          matlab.ui.control.Button
        PlotExampleButton_3            matlab.ui.control.Button
        OtherUtilitiesPanel            matlab.ui.container.Panel
        DeleteEventTriggerIndicesButton  matlab.ui.control.Button
        ArtefactSubspaceReconstructionButton  matlab.ui.control.Button
        OtherUtilitiesLabel            matlab.ui.control.Label
        StimulationArtefactRejectionButton  matlab.ui.control.Button
        CutStartandEndofRecordingButton_2  matlab.ui.control.Button
        DeleteChannelButton            matlab.ui.control.Button
    end

    
    properties (Access = public)
        Mainapp = []; % Description
        PreprocessingSteps = [];
        Info = [] % Temporarily save all preprocessing options until they are added to the mainapp object after execution of all pipeline steps
        PreprocessingFlag = []; % Saves a 1 when preprocessing was already applied in the same instance of the windo. This is to warn the user if he/she wants to start a pipeline again
        ASRWindow = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            %% Initiate GUI 
            movegui(app.PreprocessingWindowUIFigure, 'center');

            % Save Mainapp object along with Data structure here for easy
            % acces across all gui functions
            app.Mainapp = Mainapp;

            % Predefine Values
            app.CuttoffFrequencyHzEditField.Value = "300";
            app.FilterOrderEditField.Value = "3";
            app.DownsampleFactorEditField.Value = num2str(1000);
            pause(0.2);

            app.TextArea.Value = "No Preprocessing Steps added.";

            app.PreprocessingFlag = 0;

            %% Check whether preprocessing was already applied to the dataset. If so warn the user that data will be overwritten!
            if isfield(app.Mainapp.Data, 'Preprocessed')
                %f = msgbox("Warning! Data was already preprocessed. Existing preprocessed data will be overwritten if pipeline is started!");
                app.TextArea.Value{1} = 'No Preprocessing Steps added. Warning! Data was already preprocessed. Starting Pipeline will overwrite it!';
                app.TextArea.Value{2} = '';
                app.TextArea.Value{3} = 'Already Applied Preprocessing Steps:';
                app.TextArea.Value{4} = '';

                % List of fields to search for
                fieldsToSearch = {'FilterMethod', 'FilterType', 'FilterDirection','FilterOrder','Cutoff','ChannelDeletion','CutEnd','CuStart','GrandAverage','Normalize','DownsampleFactor','DownsampledSampleRate','StimArtefactChannel','TimeAroundStimArtefact'};
                
                % Initialize a cell array to store the text lines
                textLines = {};
                
                % Loop over the fields to search
                for i = 1:length(fieldsToSearch)
                    fieldName = fieldsToSearch{i};
                    
                    % Check if the field exists in the structure
                    if isfield(app.Mainapp.Data.Info, fieldName)
                        % Get the field value
                        fieldValue = app.Mainapp.Data.Info.(fieldName);
                        
                        % Create a string with the field name and value
                        if ischar(fieldValue) || isstring(fieldValue)
                            line = sprintf('%s: %s', fieldName, fieldValue);
                        elseif isnumeric(fieldValue)
                            line = sprintf('%s: %g', fieldName, fieldValue);
                        else
                            line = sprintf('%s: (unsupported type)', fieldName);
                        end
                        
                        % Append the line to the cell array
                        textLines{end+1} = line; %#ok<AGROW>
                    end
                end
                
                % Set the value of the textarea
                for i = 1:length(textLines)
                    app.TextArea.Value{i+4} = textLines{i};
                end
            else
                app.TextArea.Value = ["Create a preprocessing pipeline by clicking 'Add to Pipeline' under the desired methods you want to apply. After adding preprocessing steps and when clicking 'Start Pipeline', all preprocessing steps are applied in the order visible in this text area.";"";"No Preprocessing steps added. Add steps to the pipeline and start it to preprocess your data."];
            end

            app = Utility_Change_Light_Dark_Mode(app,"Preprocess_Window");
 
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            try
                if ~isempty(app.ASRWindow)
                    delete(app.ASRWindow);
                end
                
            catch

            end

            try 
                if ~isempty(app.Mainapp.PreproArtefactRejection)
                    delete(app.Mainapp.PreproArtefactRejection);
                end
            catch

            end

            try
                app.Mainapp.ASRWindow = [];
                app.Mainapp.PreproArtefactRejection = [];
            catch

            end
            
            try
                if isfield(app,'Mainapp')
                    app.Mainapp.PreproWindow = [];
                end
            catch

            end
            delete(app);
        end

        % Button pushed function: PlotExampleButton
        function PlotExampleButtonPushed(app, event)
            [TempInfo,TempPreprocessingSteps,~] = Preprocess_Module_Construct_Pipeline("Filter",[],[],1,app.FilterMethodDropDown.Value,app.FilterTypeDropDown.Value,app.CuttoffFrequencyHzEditField.Value,app.FilterDirectionDropDown.Value,app.FilterOrderEditField.Value,app.DownsampleFactorEditField.Value,app.Mainapp.Data.Info.NativeSamplingRate);
                        
            [~] = Preprocess_Module_Apply_Pipeline (app.Mainapp.Data,app.Mainapp.Data.Info.NativeSamplingRate,TempPreprocessingSteps,1,TempInfo,[],[],app.Mainapp.ParallelPool);
        end

        % Value changed function: FilterMethodDropDown
        function FilterMethodDropDownValueChanged(app, event)
            %% Predefined Settings when Filtertype is change i.e. from High to Low Pass filter
            value = app.FilterMethodDropDown.Value;
            app.InspectFilterButton.Enable = "on";
            
            if app.Mainapp.Data.Info.NativeSamplingRate <= 1000
                SampleRate = app.Mainapp.Data.Info.NativeSamplingRate/2;
            else
                % filter parameters
                SampleRate = 1000/2;
            end

            if strcmp(value,"Low-Pass")
                app.CuttoffFrequencyHzEditField.Enable = "on";
                app.CuttoffFrequencyHzEditField.Value = "220";
                if strcmp(app.FilterTypeDropDown.Value,"Butterworth IR")
                    order = 4;
                else
                    frange = str2double(strsplit(app.CuttoffFrequencyHzEditField.Value,','));
                    order   = round( 20*app.Mainapp.Data.Info.NativeSamplingRate/frange(1));
                end

                app.FilterOrderEditField.Value = num2str(order);
                app.FilterDirectionDropDown.Enable = "on";
                    
            elseif strcmp(value,"High-Pass")
                app.CuttoffFrequencyHzEditField.Value = "300";
                app.CuttoffFrequencyHzEditField.Enable = "on";
                if strcmp(app.FilterTypeDropDown.Value,"Butterworth IR")
                    order = 4;
                else
                    frange = str2double(strsplit(app.CuttoffFrequencyHzEditField.Value,','));
                    order   = round( 20*app.Mainapp.Data.Info.NativeSamplingRate/frange(1));
                end

                app.FilterOrderEditField.Value = num2str(order);
                app.FilterDirectionDropDown.Enable = "on";
            elseif strcmp(value,"Narrowband")
                app.CuttoffFrequencyHzEditField.Value = "12,16";
                app.CuttoffFrequencyHzEditField.Enable = "on";

                if strcmp(app.FilterTypeDropDown.Value,"Butterworth IR")
                    order = 4;
                else
                    frange = str2double(strsplit(app.CuttoffFrequencyHzEditField.Value,','));
                    order   = round( app.Mainapp.Data.Info.NativeSamplingRate/frange(1));
                end

                app.FilterOrderEditField.Value = num2str(order);
                app.FilterDirectionDropDown.Enable = "on";

            elseif strcmp(value,"Band-Stop")
                app.CuttoffFrequencyHzEditField.Value = "48,52";
                app.CuttoffFrequencyHzEditField.Enable = "on";
                if strcmp(app.FilterTypeDropDown.Value,"Butterworth IR")
                    order = 4;
                else
                    frange = str2double(strsplit(app.CuttoffFrequencyHzEditField.Value,','));
                    order   = round( 20*app.Mainapp.Data.Info.NativeSamplingRate/frange(1));
                end

                app.FilterOrderEditField.Value = num2str(order);
                app.FilterDirectionDropDown.Enable = "on";
            elseif strcmp(value,"Median Filter")
                app.CuttoffFrequencyHzEditField.Enable = "off";
                app.FilterOrderEditField.Value = "4";
                app.FilterDirectionDropDown.Enable = "off";
                app.InspectFilterButton.Enable = "off";
            end
        end

        % Button pushed function: AddtoPipelineButton
        function AddtoPipelineButtonPushed(app, event)
            %% Add Selection to the pipeline. Filterorder, cutoff and all
            %% other relevant parameters are extracted and saved here (bc multiple filter can be selected and settings have to be saved in between)!
            % Additionally checks logic if user wants to add two or more of the following
            % three filters: Low Pass, High Pass, Narrwoband. This is
            % rejected since it makes no sense. Instead, the existing added
            % filter type is replace by the most recent one added.
            value = app.FilterMethodDropDown.Value;

            if strcmp(value,"Narrowband")
                [Proceed,ExtraPrePro,~] = Preprocessing_Check_NarrowBand_Conditions(app);
                if Proceed == 0 && ExtraPrePro==0
                    return;
                end
                if ExtraPrePro==1
                    if ~isempty(app.PreprocessingSteps)
                        if ~isempty(find(app.PreprocessingSteps == "Narrowband"))
                            app.PreprocessingSteps(find(app.PreprocessingSteps == "Narrowband")) = [];
                            if isfield(app.Info,'NarrowbandFilterMethod')
                                fields = {'NarrowbandFilterMethod','NarrowbandFilterType','NarrowbandFilterDirection','NarrowbandCutoff','NarrowbandFilterOrder'};
                                app.Info = rmfield(app.Info,fields);
                            end
                        end
                    end
                    
                    
                    [app.Info,app.PreprocessingSteps,~] = Preprocess_Module_Construct_Pipeline("Filter",app.Info,app.PreprocessingSteps,0,"Low-Pass","Butterworth IR",'300',"Zero-phase forward and reverse","4","1000",app.Mainapp.Data.Info.NativeSamplingRate);
                    
                    if app.Mainapp.Data.Info.NativeSamplingRate > 1000
                        [app.Info,app.PreprocessingSteps,~] = Preprocess_Module_Construct_Pipeline("Downsample",app.Info,app.PreprocessingSteps,0,"Downsampling","Butterworth IR",'300',"Zero-phase forward and reverse","4","1000",app.Mainapp.Data.Info.NativeSamplingRate);
                    end    
                end
            elseif strcmp(value,"Band-Stop")
                [Proceed,ExtraPrePro,~] = Preprocessing_Check_Band_Stop_Conditions(app);
                if Proceed == 0 && ExtraPrePro==0
                    return;
                end
                if ExtraPrePro==1
                    if ~isempty(app.PreprocessingSteps)
                        if ~isempty(find(app.PreprocessingSteps == "Band-Stop"))
                            app.PreprocessingSteps(find(app.PreprocessingSteps == "Band-Stop")) = [];
                            if isfield(app.Info,'BandstopFilterMethod')
                                fields = {'NarrowbandFilterMethod','NarrowbandFilterType','NarrowbandFilterDirection','NarrowbandCutoff','NarrowbandFilterOrder'};
                                app.Info = rmfield(app.Info,fields);
                            end
                        end
                    end
                    [app.Info,app.PreprocessingSteps,~] = Preprocess_Module_Construct_Pipeline("Filter",app.Info,app.PreprocessingSteps,0,"Low-Pass","Butterworth IR",'300',"Zero-phase forward and reverse","4","1000",app.Mainapp.Data.Info.NativeSamplingRate);
                    if app.Mainapp.Data.Info.NativeSamplingRate > 1000
                        [app.Info,app.PreprocessingSteps,~] = Preprocess_Module_Construct_Pipeline("Downsample",app.Info,app.PreprocessingSteps,0,"Downsampling","Butterworth IR",'300',"Zero-phase forward and reverse","4","1000",app.Mainapp.Data.Info.NativeSamplingRate);
                    end
                end
            end


            [app.Info,app.PreprocessingSteps,InfoText] = Preprocess_Module_Construct_Pipeline("Filter",app.Info,app.PreprocessingSteps,0,app.FilterMethodDropDown.Value,app.FilterTypeDropDown.Value,app.CuttoffFrequencyHzEditField.Value,app.FilterDirectionDropDown.Value,app.FilterOrderEditField.Value,app.DownsampleFactorEditField.Value,app.Mainapp.Data.Info.NativeSamplingRate);
            if ~isempty(InfoText)
                app.TextArea.Value = InfoText;
            end
        end

        % Button pushed function: AddtoPipelineButton_2
        function AddtoPipelineButton_2Pushed(app, event)
            % Add Selection to the pipeline. Filterorder, cutoff and all
            %% other relevant parameters are extracted and saved here (bc multiple filter can be selected and settings have to be saved in between)!
            % Additionally checks logic if user wants to add two or more of the following
            % three filters: Low Pass, High Pass, Narrwoband. This is
            % rejected since it makes no sense. Instead, the existing added
            % filter type is replace by the most recent one added.
            [app.Info,app.PreprocessingSteps,InfoText] = Preprocess_Module_Construct_Pipeline("Downsample",app.Info,app.PreprocessingSteps,0,app.FilterMethodDropDown.Value,app.FilterTypeDropDown.Value,app.CuttoffFrequencyHzEditField.Value,app.FilterDirectionDropDown.Value,app.FilterOrderEditField.Value,app.DownsampleFactorEditField.Value,app.Mainapp.Data.Info.NativeSamplingRate);
            
            if app.Info.DownsampledSampleRate ~= str2double(app.DownsampleFactorEditField.Value)
                app.DownsampleFactorEditField.Value = num2str(app.Info.DownsampledSampleRate);
            end

            if ~isempty(InfoText)
                app.TextArea.Value = InfoText;
            end
        end

        % Button pushed function: AddtoPipelineButton_3
        function AddtoPipelineButton_3Pushed(app, event)
            % Add Selection to the pipeline. Filterorder, cutoff and all
            %% other relevant parameters are extracted and saved here (bc multiple filter can be selected and settings have to be saved in between)!
            % Additionally checks logic if user wants to add two or more of the following
            % three filters: Low Pass, High Pass, Narrwoband. This is
            % rejected since it makes no sense. Instead, the existing added
            % filter type is replace by the most recent one added.
            [app.Info,app.PreprocessingSteps,InfoText] = Preprocess_Module_Construct_Pipeline("Normalize",app.Info,app.PreprocessingSteps,0,app.FilterMethodDropDown.Value,app.FilterTypeDropDown.Value,app.CuttoffFrequencyHzEditField.Value,app.FilterDirectionDropDown.Value,app.FilterOrderEditField.Value,app.DownsampleFactorEditField.Value,app.Mainapp.Data.Info.NativeSamplingRate);
            if ~isempty(InfoText)
                app.TextArea.Value = InfoText;
            end
        end

        % Button pushed function: AddtoPipelineButton_4
        function AddtoPipelineButton_4Pushed(app, event)
            % Add Selection to the pipeline. Filterorder, cutoff and all
            %% other relevant parameters are extracted and saved here (bc multiple filter can be selected and settings have to be saved in between)!
            % Additionally checks logic if user wants to add two or more of the following
            % three filters: Low Pass, High Pass, Narrwoband. This is
            % rejected since it makes no sense. Instead, the existing added
            % filter type is replace by the most recent one added.
            [app.Info,app.PreprocessingSteps,InfoText] = Preprocess_Module_Construct_Pipeline("GrandAverage",app.Info,app.PreprocessingSteps,0,app.FilterMethodDropDown.Value,app.FilterTypeDropDown.Value,app.CuttoffFrequencyHzEditField.Value,app.FilterDirectionDropDown.Value,app.FilterOrderEditField.Value,app.DownsampleFactorEditField.Value,app.Mainapp.Data.Info.NativeSamplingRate);
            if ~isempty(InfoText)
                app.TextArea.Value = InfoText;
            end
        end

        % Button pushed function: StartPipelineButton
        function StartPipelineButtonPushed(app, event)
            % If preprocesing already applied in the same instance of this
            % window, warn the user that previous data will be overwritten
                        
            if isempty(app.PreprocessingSteps)
                f = msgbox("Error: No pipeline components added");
                return;
            end

            PreviousChannelDeletetion = 0;
            % If Channel were deleted in previous preprocessing flag this
            % to reset main window parameter after + only allow for prepro
            % view if prepro was generated
            if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                PreviousChannelDeletetion = 1;
            end

            % Set the flag to 1 that indicates that preproc. was applied in
            % this window instance
            app.PreprocessingFlag = 1;

            if isfield(app.Info,'ChannelDeletion')
                ChannelDeletion = app.Info.ChannelDeletion;
            else
                ChannelDeletion = [];
            end
            
            CutstartEnd = 0;
            if isfield(app.Info,'CutStart') || isfield(app.Info,'CutEnd') || isfield(app.Info,'DownsampleFactor')
                app.Mainapp.CurrentTimePoints = 1;
                if isfield(app.Info,'CutStart') || isfield(app.Info,'CutEnd')
                    CutstartEnd = 1;
                    if isfield(app.Mainapp.Data,'Events')
                        [app.Mainapp.Data,~] = Organize_Delete_Dataset_Components(app.Mainapp.Data,"Events");
                    end
                end
            end
            if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                app.Mainapp.CurrentTimePoints = 1;
            end
            %% First overwrite old preprocessing infos from structure if data was already preprocesseds --> Old preprocessing is overwritten!
            [app.Mainapp.Data,app.Info,app.TextArea] = Preprocess_Module_Delete_Old_Settings(app.Mainapp.Data,app.Info,app.PreprocessingSteps,ChannelDeletion,app.TextArea);
            
            %% Loop over Preprocessing Steps. app Input necessary to extract filterorder, cutoff and so on from app.Info object saving the prepr. parameters. 
            % Preprocessing itself can also run without this if all the
            % necessary parameters are defined manually (see in function for further detail)
            
            f = [];

            [app.Mainapp.Data] = Preprocess_Module_Apply_Pipeline(app.Mainapp.Data,app.Mainapp.Data.Info.NativeSamplingRate,app.PreprocessingSteps,0,app.Info,ChannelDeletion,app.TextArea,app.Mainapp.ParallelPool);
            
            if strcmp(app.Mainapp.PlotAppearance.MainWindow.Data.Plottype, "AxonViewer")
                if isfield(app.Mainapp.Data.Info,'FilterMethod')
                    if ~strcmp(app.Mainapp.Data.Info.FilterMethod,"High-Pass") && ~strcmp(app.Mainapp.Data.Info.FilterMethod,"Band-Pass")
                        msgbox("Data needs to be high-pass or band-pass filtered to track high-frequency components in Axon Viewer.")
                        app.Mainapp.PlotAppearance.MainWindow.Data.Plottype="Surf";
                    end
                else
                    msgbox("Data needs to be high-pass or band-pass filtered to track high-frequency components in Axon Viewer.")
                    app.Mainapp.PlotAppearance.MainWindow.Data.Plottype="Surf";
                end
            end

            Organize_Delete_All_Open_Windows(app.Mainapp,0);
            try
                if ~isempty(app.Mainapp.EventExtractionWindow)
                    if isvalid(app.Mainapp.EventExtractionWindow)
                        delete(app.Mainapp.EventExtractionWindow);
                        app.Mainapp.EventExtractionWindow = [];
                    end
                end
            end

            if isempty(app.Mainapp.ProbeViewWindowHandle)
                app.Mainapp.ProbeViewWindowHandle = Probe_View_Window(app.Mainapp,'MainWindow');
            end
            % account for channeldeltion in currently actice channel by
            % just overwriting with all new active channel
            if isfield(app.Info,'ChannelDeletion') || isfield(app.Info,'ASR')

                app.Mainapp.PowerSpecResults = [];
                app.Mainapp.ActiveChannel = app.Mainapp.Data.Info.ProbeInfo.ActiveChannel;

                if isfield(app.Mainapp.Data.Info.ProbeInfo,'CompleteAreaNames')
                    BrainAreaInfo.CompleteAreaNames = app.Mainapp.Data.Info.ProbeInfo.CompleteAreaNames;
                    BrainAreaInfo.ShortAreaNames = app.Mainapp.Data.Info.ProbeInfo.ShortAreaNames;
                    BrainAreaInfo.AreaDistanceFromTip = app.Mainapp.Data.Info.ProbeInfo.AreaDistanceFromTip;
                else
                    BrainAreaInfo = [];
                end

                if ~isempty(app.Mainapp.ProbeViewWindowHandle)
                    if str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows) == 1
                        
                        app.Mainapp.ProbeViewWindowHandle.FirstZoomChannel = str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)-32;            

                        Utility_Plot_Interactive_Probe_View(app.Mainapp.ProbeViewWindowHandle.UIAxes,app.Mainapp.Data.Info.ChannelSpacing,str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel),str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows),str2double(app.Mainapp.Data.Info.ProbeInfo.HorOffset),str2double(app.Mainapp.Data.Info.ProbeInfo.VertOffset),app.Mainapp.Data.Info.ProbeInfo.OffSetRowsDistance,app.Mainapp.ActiveChannel,app.Mainapp.ProbeViewWindowHandle.FirstZoomChannel,1,BrainAreaInfo,app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.Mainapp.ProbeViewWindowHandle.ShowChannelSpacingCheckBox.Value,1,1,app.Mainapp.ActiveChannel,app.Mainapp.Data.Info.ProbeInfo.OffSetRows,[],app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel,app.Mainapp.Data.Info.ProbeInfo.SwitchLeftRightChannel,app.Mainapp.Data.Info.ProbeInfo.ECoGArray)
                    else
                        app.Mainapp.ProbeViewWindowHandle.FirstZoomChannel = str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel);
                        Utility_Plot_Interactive_Probe_View(app.Mainapp.ProbeViewWindowHandle.UIAxes,app.Mainapp.Data.Info.ChannelSpacing,str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel),str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows),str2double(app.Mainapp.Data.Info.ProbeInfo.HorOffset),str2double(app.Mainapp.Data.Info.ProbeInfo.VertOffset),app.Mainapp.Data.Info.ProbeInfo.OffSetRowsDistance,app.Mainapp.ActiveChannel,app.Mainapp.ProbeViewWindowHandle.FirstZoomChannel,1,BrainAreaInfo,app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.Mainapp.ProbeViewWindowHandle.ShowChannelSpacingCheckBox.Value,1,1,app.Mainapp.ActiveChannel,app.Mainapp.Data.Info.ProbeInfo.OffSetRows,[],app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel,app.Mainapp.Data.Info.ProbeInfo.SwitchLeftRightChannel,app.Mainapp.Data.Info.ProbeInfo.ECoGArray)
                    end
                                
                end
            end
            
            if isfield(app.Mainapp.Data,'Events')
                if ~isempty(app.Mainapp.Data.Events)
                    for i = 1:length(app.Mainapp.Data.Events)
                        texttoshow{i} =  app.Mainapp.Data.Info.EventChannelNames{i};
                    end
                    app.Mainapp.EventChannelDropDown.Items = texttoshow;
                end
            end

            %% Update Textbox "Recording Information" in Main window with new preprocessing infos
            Utility_Show_Info_Loaded_Data(app.Mainapp);

            [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);

            %% Wrap up
            
            app.PreprocessingSteps = [];
            app.Info = [];
            app.TextArea.Value{1} = 'Preprocessing Finished';
            app.TextArea.Value{2} = '';
            app.TextArea.Value{3} = 'Applied Preprocessing Steps:';
            app.TextArea.Value{4} = '';

            % List of fields to search for
            fieldsToSearch = {'FilterMethod', 'FilterType', 'FilterDirection','FilterOrder','Cutoff','ChannelDeletion','CutEnd','CutStart','GrandAverage','Normalize','DownsampleFactor','DownsampledSampleRate','TimeAroundStimArtefact'};
            
            % Initialize a cell array to store the text lines
            textLines = {};
            
            % Loop over the fields to search
            for i = 1:length(fieldsToSearch)
                fieldName = fieldsToSearch{i};
                
                % Check if the field exists in the structure
                if isfield(app.Mainapp.Data.Info, fieldName)
                    % Get the field value
                    fieldValue = app.Mainapp.Data.Info.(fieldName);
                    
                    % Create a string with the field name and value
                    if ischar(fieldValue) || isstring(fieldValue)
                        line = sprintf('%s: %s', fieldName, fieldValue);
                    elseif isnumeric(fieldValue)
                        if length(fieldValue) == 2
                            line = sprintf('%s: %d %d', fieldName, fieldValue(1),fieldValue(2));
                        else
                            line = sprintf('%s: %g', fieldName, fieldValue);
                        end
                    else
                        line = sprintf('%s: (unsupported type)', fieldName);
                    end
                    
                    % Append the line to the cell array
                    textLines{end+1} = line; %#ok<AGROW>
                end
            end
            
            % Set the value of the textarea
            for i = 1:length(textLines)
                app.TextArea.Value{i+4} = textLines{i};
            end

            
            a = find(app.Mainapp.TimeRangeViewBox.Value=='s');
            CurrentTimeSpan = str2double(app.Mainapp.TimeRangeViewBox.Value(1:a(1)-1));
            
            if app.Mainapp.Data.Time(end) < 1
                msgbox("Error. Less then 1 second of recording available. This probably causes conflicts with the current time span being plotted!")
            end

            if CurrentTimeSpan>app.Mainapp.Data.Time(end)
                app.Mainapp.TimeLimit = [0.0333,3]; % sets maximum and minimum amount of time shown in Main Window Plot
                TimeRangeText = strcat(num2str(1),"s");
                app.Mainapp.TimeRangeViewBox.Value = TimeRangeText;
            end

            [app.Mainapp] = Organize_Set_MainWindow_Dropdown(app.Mainapp,app.Mainapp.Data);

            KeepSpikes = 0;
            KeepEvents = 0;
            if isfield(app.Mainapp.Data,'Spikes') && strcmp(app.Mainapp.Plotspikes,"Spikes")
                KeepSpikes = 1;
            end
            if isfield(app.Mainapp.Data,'Events') && strcmp(app.Mainapp.PlotEvents,"Events")
                KeepEvents = 1;
            end
            
            if CutstartEnd && ~isempty(app.Mainapp.EventChannelDropDown.Items)
                app.Mainapp.EventChannelDropDown.Value = app.Mainapp.EventChannelDropDown.Items{1};
            end
            
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Subsequent","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            Organize_Reset_Main_Plot(app.Mainapp,1,KeepEvents,KeepSpikes,0,app.Mainapp.CurrentTimePoints);
            
            app.Mainapp = Utility_Change_Light_Dark_Mode(app.Mainapp,"MainWindow");

        end

        % Button pushed function: PlotExampleButton_2
        function PlotExampleButton_2Pushed(app, event)
            
            [TempInfo,TempPreprocessingSteps,~] = Preprocess_Module_Construct_Pipeline("Downsample",[],[],1,app.FilterMethodDropDown.Value,app.FilterTypeDropDown.Value,app.CuttoffFrequencyHzEditField.Value,app.FilterDirectionDropDown.Value,app.FilterOrderEditField.Value,app.DownsampleFactorEditField.Value,app.Mainapp.Data.Info.NativeSamplingRate);

            [~] = Preprocess_Module_Apply_Pipeline (app.Mainapp.Data,app.Mainapp.Data.Info.NativeSamplingRate,TempPreprocessingSteps,1,TempInfo,[],[],app.Mainapp.ParallelPool);

        end

        % Button pushed function: PlotExampleButton_4
        function PlotExampleButton_4Pushed(app, event)
            [TempInfo,TempPreprocessingSteps,~] = Preprocess_Module_Construct_Pipeline("Normalize",[],[],1,app.FilterMethodDropDown.Value,app.FilterTypeDropDown.Value,app.CuttoffFrequencyHzEditField.Value,app.FilterDirectionDropDown.Value,app.FilterOrderEditField.Value,app.DownsampleFactorEditField.Value,app.Mainapp.Data.Info.NativeSamplingRate);

            [~] = Preprocess_Module_Apply_Pipeline (app.Mainapp.Data,app.Mainapp.Data.Info.NativeSamplingRate,TempPreprocessingSteps,1,TempInfo,[],[],app.Mainapp.ParallelPool);
        end

        % Button pushed function: PlotExampleButton_3
        function PlotExampleButton_3Pushed(app, event)
            [TempInfo,TempPreprocessingSteps,~] = Preprocess_Module_Construct_Pipeline("GrandAverage",[],[],1,app.FilterMethodDropDown.Value,app.FilterTypeDropDown.Value,app.CuttoffFrequencyHzEditField.Value,app.FilterDirectionDropDown.Value,app.FilterOrderEditField.Value,app.DownsampleFactorEditField.Value,app.Mainapp.Data.Info.NativeSamplingRate);
            
            [~] = Preprocess_Module_Apply_Pipeline (app.Mainapp.Data,app.Mainapp.Data.Info.NativeSamplingRate,TempPreprocessingSteps,1,TempInfo,[],[],app.Mainapp.ParallelPool);
        end

        % Button pushed function: DeleteLastPipelineEntryButton
        function DeleteLastPipelineEntryButtonPushed(app, event)
            if isempty(app.PreprocessingSteps)
                f = msgbox("Error: No pipeline components added");
                return;
            end

            app = Preprocess_DeleteLastPipeline_Entry(app);

            [app.Info,app.PreprocessingSteps,~] = Preprocess_Module_Construct_Pipeline("",app.Info,app.PreprocessingSteps,0,app.FilterMethodDropDown.Value,app.FilterTypeDropDown.Value,app.CuttoffFrequencyHzEditField.Value,app.FilterDirectionDropDown.Value,app.FilterOrderEditField.Value,app.DownsampleFactorEditField.Value,app.Mainapp.Data.Info.NativeSamplingRate);

        end

        % Value changed function: FilterOrderEditField
        function FilterOrderEditFieldValueChanged(app, event)
            [app.FilterOrderEditField.Value] = Utility_SimpleCheckInputs(app.FilterOrderEditField.Value,"One",'3',0,0);

        end

        % Value changed function: DownsampleFactorEditField
        function DownsampleFactorEditFieldValueChanged(app, event)

            [app.DownsampleFactorEditField.Value] = Utility_SimpleCheckInputs(app.DownsampleFactorEditField.Value,"One",'1000',0,0);
            
            downsamplerate = str2double(app.DownsampleFactorEditField.Value);
            if downsamplerate>app.Mainapp.Data.Info.NativeSamplingRate
                msgbox("Error: Can not be higher than native samplerate!")
                return;
            end

        end

        % Value changed function: CuttoffFrequencyHzEditField
        function CuttoffFrequencyHzEditFieldValueChanged(app, event)
             if strcmp(app.FilterMethodDropDown.Value,"High-Pass") || strcmp(app.FilterMethodDropDown.Value,"Low-Pass")
                [app.CuttoffFrequencyHzEditField.Value] = Utility_SimpleCheckInputs(app.CuttoffFrequencyHzEditField.Value,"One",'300',0,0);
             elseif strcmp(app.FilterMethodDropDown.Value,"Narrowband") || strcmp(app.FilterMethodDropDown.Value,"Band-Stop")
                [app.CuttoffFrequencyHzEditField.Value] = Utility_SimpleCheckInputs(app.CuttoffFrequencyHzEditField.Value,"Two",'48,52',0,0);
             end


             %% Predefined Settings when Filtertype is change i.e. from High to Low Pass filter
            value = app.FilterMethodDropDown.Value;

            if strcmp(value,"Low-Pass")

                if strcmp(app.FilterTypeDropDown.Value,"Butterworth IR")
                    order = 4;
                else
                    frange = str2double(strsplit(app.CuttoffFrequencyHzEditField.Value,','));
                    order   = round( 20*app.Mainapp.Data.Info.NativeSamplingRate/frange(1));
                end

                app.FilterOrderEditField.Value = num2str(order);
                    
            elseif strcmp(value,"High-Pass")

                if strcmp(app.FilterTypeDropDown.Value,"Butterworth IR")
                    order = 4;
                else
                    frange = str2double(strsplit(app.CuttoffFrequencyHzEditField.Value,','));
                    order   = round( 20*app.Mainapp.Data.Info.NativeSamplingRate/frange(1));
                end

                app.FilterOrderEditField.Value = num2str(order);

            elseif strcmp(value,"Narrowband")
                
                if strcmp(app.FilterTypeDropDown.Value,"Butterworth IR")
                    order = 4;
                else
                    frange = str2double(strsplit(app.CuttoffFrequencyHzEditField.Value,','));
                    order   = round( app.Mainapp.Data.Info.NativeSamplingRate/frange(1));
                end

                app.FilterOrderEditField.Value = num2str(order);

            elseif strcmp(value,"Band-Stop")

                if strcmp(app.FilterTypeDropDown.Value,"Butterworth IR")
                    order = 4;
                else
                    frange = str2double(strsplit(app.CuttoffFrequencyHzEditField.Value,','));
                    order   = round( 20*app.Mainapp.Data.Info.NativeSamplingRate/frange(1));
                end

                app.FilterOrderEditField.Value = num2str(order);
            end

        end

        % Button pushed function: DeleteChannelButton
        function DeleteChannelButtonPushed(app, event)
            Channel_Deletion_Selection(app);
        end

        % Button pushed function: CutStartandEndofRecordingButton_2
        function CutStartandEndofRecordingButton_2Pushed(app, event)
            
            Cut_Recording_Window(app);
        end

        % Button pushed function: StimulationArtefactRejectionButton
        function StimulationArtefactRejectionButtonPushed(app, event)
            if isfield(app.Mainapp.Data,'Events')
                if ~isempty(app.Mainapp.Data.Events)

                    if isempty(app.Mainapp.ProbeViewWindowHandle) || ~isprop(app.Mainapp.ProbeViewWindowHandle,'ProbeViewUIFigure')
                        app.Mainapp.ProbeViewWindowHandle = Probe_View_Window(app.Mainapp,'MainWindow');
                    end

                    if ~isempty(app.Mainapp.ProbeViewWindowHandle) && isprop(app.Mainapp.ProbeViewWindowHandle,'ProbeViewUIFigure') % Add option to probe view when available
                        AlreadyIn = 0;
                        for i = 1:length(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                            if strcmp(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Prepro Artefact Rejection')
                                AlreadyIn = 1;
                            end
                        end
                        if AlreadyIn == 0
                            app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{end+1} = 'Prepro Artefact Rejection';
                        end 
                    end
                
                    app.Mainapp.PreproArtefactRejection = Artefact_Rejection_Continous_Window(app);

                    [app.Mainapp] = Utility_Set_ToolTips(app.Mainapp,app.Mainapp.ShowToolTipsSetting,"StimArtefactRejection");

                else
                    msgbox("Error: No event channel found which are necessary to determine stimulation time points.")
                end
            else
                msgbox("Error: No event channel found which are necessary to determine stimulation time points.")
            end
        end

        % Button pushed function: InspectFilterButton
        function InspectFilterButtonPushed(app, event)
            [TempInfo,TempPreprocessingSteps,~] = Preprocess_Module_Construct_Pipeline("Filter",[],[],1,app.FilterMethodDropDown.Value,app.FilterTypeDropDown.Value,app.CuttoffFrequencyHzEditField.Value,app.FilterDirectionDropDown.Value,app.FilterOrderEditField.Value,app.DownsampleFactorEditField.Value,app.Mainapp.Data.Info.NativeSamplingRate);
            
            TempInfo.FilterKernel = 1;

            [~] = Preprocess_Module_Apply_Pipeline (app.Mainapp.Data,app.Mainapp.Data.Info.NativeSamplingRate,TempPreprocessingSteps,1,TempInfo,[],[],app.Mainapp.ParallelPool);
        end

        % Close request function: PreprocessingWindowUIFigure
        function PreprocessingWindowUIFigureCloseRequest(app, event)
            try
                if ~isempty(app.ASRWindow)
                    delete(app.ASRWindow);
                end
                
            catch

            end

            try 
                if ~isempty(app.Mainapp.PreproArtefactRejection)
                    delete(app.Mainapp.PreproArtefactRejection);
                end
            catch

            end

            try
                app.Mainapp.ASRWindow = [];
                app.Mainapp.PreproArtefactRejection = [];
            catch

            end

            try
                if isfield(app,'Mainapp')
                    app.Mainapp.PreproWindow = [];
                end
            catch

            end

            delete(app);
        end

        % Value changed function: FilterTypeDropDown
        function FilterTypeDropDownValueChanged(app, event)
            value = app.FilterTypeDropDown.Value;
            if strcmp(value,'FIR-1')
                if strcmp(value,"Narrowband")
                    frange =  str2double(strsplit(app.CuttoffFrequencyHzEditField.Value,','));
                    order   = round(app.Mainapp.Data.Info.NativeSamplingRate/frange(1));
                else
                    frange =  str2double(strsplit(app.CuttoffFrequencyHzEditField.Value,','));
                    order   = round( 20*app.Mainapp.Data.Info.NativeSamplingRate/frange(1));
                end
            else
                order = 4;
            end

            app.FilterOrderEditField.Value = num2str(order);
        end

        % Button pushed function: ArtefactSubspaceReconstructionButton
        function ArtefactSubspaceReconstructionButtonPushed(app, event)
            app.ASRWindow = ASR_Window(app.Mainapp,app);        
            app.Mainapp.ASRWindow = app.ASRWindow;
            [app.Mainapp] = Utility_Set_ToolTips(app.Mainapp,app.Mainapp.ShowToolTipsSetting,"ASR");
        end

        % Button pushed function: DeleteEventTriggerIndicesButton
        function DeleteEventTriggerIndicesButtonPushed(app, event)
            if isfield(app.Mainapp.Data,'Events')
                EventsRelatedApp.TextArea = [];
                app.Mainapp.EventIndiceRejectionWindow = Preprocessing_Events_Trial_Rejection_Window(app.Mainapp,EventsRelatedApp,"Preprocessing");
            else
                msgbox("Error: No event data found.");
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PreprocessingWindowUIFigure = uifigure('Visible', 'off');
            app.PreprocessingWindowUIFigure.Name = 'Preprocessing Window';
            app.PreprocessingWindowUIFigure.Icon = 'Logo.png';
            app.PreprocessingWindowUIFigure.CloseRequestFcn = createCallbackFcn(app, @PreprocessingWindowUIFigureCloseRequest, true);
            app.OtherUtilitiesPanel = uipanel(app.PreprocessingWindowUIFigure);
            app.OtherUtilitiesPanel.Title = 'Other Utilities';
            app.DeleteChannelButton = uibutton(app.OtherUtilitiesPanel, 'push');
            app.DeleteChannelButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteChannelButtonPushed, true);
            app.DeleteChannelButton.Text = 'Delete Channel';
            app.CutStartandEndofRecordingButton_2 = uibutton(app.OtherUtilitiesPanel, 'push');
            app.CutStartandEndofRecordingButton_2.ButtonPushedFcn = createCallbackFcn(app, @CutStartandEndofRecordingButton_2Pushed, true);
            app.CutStartandEndofRecordingButton_2.Text = {'Cut Start and End'; 'of Recording'};
            app.StimulationArtefactRejectionButton = uibutton(app.OtherUtilitiesPanel, 'push');
            app.StimulationArtefactRejectionButton.ButtonPushedFcn = createCallbackFcn(app, @StimulationArtefactRejectionButtonPushed, true);
            app.StimulationArtefactRejectionButton.Text = {'Stimulation Artefact '; 'Rejection'};
            app.OtherUtilitiesLabel = uilabel(app.OtherUtilitiesPanel);
            app.OtherUtilitiesLabel.Text = 'Other Utilities';
            app.ArtefactSubspaceReconstructionButton = uibutton(app.OtherUtilitiesPanel, 'push');
            app.ArtefactSubspaceReconstructionButton.ButtonPushedFcn = createCallbackFcn(app, @ArtefactSubspaceReconstructionButtonPushed, true);
            app.ArtefactSubspaceReconstructionButton.Text = {'Artefact Subspace'; 'Reconstruction'};
            app.DeleteEventTriggerIndicesButton = uibutton(app.OtherUtilitiesPanel, 'push');
            app.DeleteEventTriggerIndicesButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteEventTriggerIndicesButtonPushed, true);
            app.DeleteEventTriggerIndicesButton.Text = {'Delete Event Trigger'; 'Indices'};
            app.GrandAveragePanel = uipanel(app.PreprocessingWindowUIFigure);
            app.GrandAveragePanel.Title = 'Grand Average';
            app.PlotExampleButton_3 = uibutton(app.GrandAveragePanel, 'push');
            app.PlotExampleButton_3.ButtonPushedFcn = createCallbackFcn(app, @PlotExampleButton_3Pushed, true);
            app.PlotExampleButton_3.Text = 'Plot Example';
            app.AddtoPipelineButton_4 = uibutton(app.GrandAveragePanel, 'push');
            app.AddtoPipelineButton_4.ButtonPushedFcn = createCallbackFcn(app, @AddtoPipelineButton_4Pushed, true);
            app.AddtoPipelineButton_4.Text = 'Add to Pipeline';
            app.TextArea = uitextarea(app.PreprocessingWindowUIFigure);
            app.TextArea.Editable = 'off';
            app.PipelineComponentsLabel = uilabel(app.PreprocessingWindowUIFigure);
            app.PipelineComponentsLabel.Text = 'Pipeline Components';
            app.StartPipelineButton = uibutton(app.PreprocessingWindowUIFigure, 'push');
            app.StartPipelineButton.ButtonPushedFcn = createCallbackFcn(app, @StartPipelineButtonPushed, true);
            app.StartPipelineButton.Text = 'Start Pipeline';
            app.DeleteLastPipelineEntryButton = uibutton(app.PreprocessingWindowUIFigure, 'push');
            app.DeleteLastPipelineEntryButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteLastPipelineEntryButtonPushed, true);
            app.DeleteLastPipelineEntryButton.Text = {'Delete Last '; 'Pipeline Entry'};
            app.GrandAverageLabel = uilabel(app.PreprocessingWindowUIFigure);
            app.GrandAverageLabel.Text = 'Grand Average';
            app.CloseButton = uibutton(app.PreprocessingWindowUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.DownsamplingPanel = uipanel(app.PreprocessingWindowUIFigure);
            app.DownsamplingPanel.Title = 'Downsampling';
            app.PlotExampleButton_2 = uibutton(app.DownsamplingPanel, 'push');
            app.PlotExampleButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotExampleButton_2Pushed, true);
            app.PlotExampleButton_2.Text = 'Plot Example';
            app.AddtoPipelineButton_2 = uibutton(app.DownsamplingPanel, 'push');
            app.AddtoPipelineButton_2.ButtonPushedFcn = createCallbackFcn(app, @AddtoPipelineButton_2Pushed, true);
            app.AddtoPipelineButton_2.Text = 'Add to Pipeline';
            app.DownsampleFactorLabel = uilabel(app.DownsamplingPanel);
            app.DownsampleFactorLabel.Text = {'New Sampling Rate'; '(Hz)'};
            app.DownsampleFactorEditField = uieditfield(app.DownsamplingPanel, 'text');
            app.DownsampleFactorEditField.ValueChangedFcn = createCallbackFcn(app, @DownsampleFactorEditFieldValueChanged, true);
            app.NormalizePanel = uipanel(app.PreprocessingWindowUIFigure);
            app.NormalizePanel.Title = 'Normalize';
            app.PlotExampleButton_4 = uibutton(app.NormalizePanel, 'push');
            app.PlotExampleButton_4.ButtonPushedFcn = createCallbackFcn(app, @PlotExampleButton_4Pushed, true);
            app.PlotExampleButton_4.Text = 'Plot Example';
            app.AddtoPipelineButton_3 = uibutton(app.NormalizePanel, 'push');
            app.AddtoPipelineButton_3.ButtonPushedFcn = createCallbackFcn(app, @AddtoPipelineButton_3Pushed, true);
            app.AddtoPipelineButton_3.Text = 'Add to Pipeline';
            app.DownsamplingLabel = uilabel(app.PreprocessingWindowUIFigure);
            app.DownsamplingLabel.Text = 'Downsampling';
            app.NormalizeLabel = uilabel(app.PreprocessingWindowUIFigure);
            app.NormalizeLabel.Text = 'Normalize';
            app.FilteringPanel = uipanel(app.PreprocessingWindowUIFigure);
            app.FilteringPanel.Title = 'Filtering';
            app.PlotExampleButton = uibutton(app.FilteringPanel, 'push');
            app.PlotExampleButton.ButtonPushedFcn = createCallbackFcn(app, @PlotExampleButtonPushed, true);
            app.PlotExampleButton.Text = 'Plot Example';
            app.AddtoPipelineButton = uibutton(app.FilteringPanel, 'push');
            app.AddtoPipelineButton.ButtonPushedFcn = createCallbackFcn(app, @AddtoPipelineButtonPushed, true);
            app.AddtoPipelineButton.Text = 'Add to Pipeline';
            app.FilterMethodDropDownLabel = uilabel(app.FilteringPanel);
            app.FilterMethodDropDownLabel.Text = 'Filter Method';
            app.FilterMethodDropDown = uidropdown(app.FilteringPanel);
            app.FilterMethodDropDown.Items = {'High-Pass', 'Low-Pass', 'Narrowband', 'Band-Stop', 'Median Filter'};
            app.FilterMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterMethodDropDownValueChanged, true);
            app.FilterMethodDropDown.Value = 'High-Pass';
            app.FilterTypeDropDownLabel = uilabel(app.FilteringPanel);
            app.FilterTypeDropDownLabel.Text = 'Filter Type';
            app.FilterTypeDropDown = uidropdown(app.FilteringPanel);
            app.FilterTypeDropDown.Items = {'Butterworth IR', 'FIR-1'};
            app.FilterTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterTypeDropDownValueChanged, true);
            app.FilterTypeDropDown.Value = 'Butterworth IR';
            app.CuttoffFrequencyHzLabel = uilabel(app.FilteringPanel);
            app.CuttoffFrequencyHzLabel.Text = {'Cuttoff '; 'Frequency [Hz]'};
            app.CuttoffFrequencyHzEditField = uieditfield(app.FilteringPanel, 'text');
            app.CuttoffFrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @CuttoffFrequencyHzEditFieldValueChanged, true);
            app.FilterOrderEditFieldLabel = uilabel(app.FilteringPanel);
            app.FilterOrderEditFieldLabel.Text = 'Filter Order';
            app.FilterOrderEditField = uieditfield(app.FilteringPanel, 'text');
            app.FilterOrderEditField.ValueChangedFcn = createCallbackFcn(app, @FilterOrderEditFieldValueChanged, true);
            app.FilterOrderEditField.Value = '3';
            app.InspectFilterButton = uibutton(app.FilteringPanel, 'push');
            app.InspectFilterButton.ButtonPushedFcn = createCallbackFcn(app, @InspectFilterButtonPushed, true);
            app.InspectFilterButton.Text = 'Inspect Filter';
            app.FilteringLabel = uilabel(app.PreprocessingWindowUIFigure);
            app.FilteringLabel.Text = 'Filtering';
            app.FilterDirectionDropDownLabel = uilabel(app.PreprocessingWindowUIFigure);
            app.FilterDirectionDropDownLabel.Enable = 'off';
            app.FilterDirectionDropDownLabel.Visible = 'off';
            app.FilterDirectionDropDownLabel.Text = 'Filter Direction';
            app.FilterDirectionDropDown = uidropdown(app.PreprocessingWindowUIFigure);
            app.FilterDirectionDropDown.Items = {'Forward', 'Reverse', 'Zero-phase forward and reverse', 'Zero-phase reverse and forward'};
            app.FilterDirectionDropDown.Enable = 'off';
            app.FilterDirectionDropDown.Visible = 'off';
            app.FilterDirectionDropDown.Value = 'Zero-phase forward and reverse';
            % Show the figure after all components are created
            app.PreprocessingWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Preprocessing_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.PreprocessingWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.PreprocessingWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PreprocessingWindowUIFigure)
        end
    end
end