classdef Continuous_Spike_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ContinuousSpikeAnalysisUIFigure  matlab.ui.Figure
        SnapshotMenu                    matlab.ui.container.Menu
        SaveSnapshotofWindowMenu        matlab.ui.container.Menu
        PNGMenu                         matlab.ui.container.Menu
        JPGMenu                         matlab.ui.container.Menu
        SVGMenu                         matlab.ui.container.Menu
        PlotMenu                        matlab.ui.container.Menu
        Show2DimensionalPlotsMenu       matlab.ui.container.Menu
        Show3DimensionalPlotsMenu       matlab.ui.container.Menu
        ChangePlotPropertiesMenu        matlab.ui.container.Menu
        SpikeDataPlotMenu               matlab.ui.container.Menu
        FontSizeMenu                    matlab.ui.container.Menu
        LineWidthSpikesMenu             matlab.ui.container.Menu
        LineWidthTriggerMenu            matlab.ui.container.Menu
        ColorSpikesMenu                 matlab.ui.container.Menu
        ColorTriggerMenu                matlab.ui.container.Menu
        BackgorundColorMenu             matlab.ui.container.Menu
        XLabelMenu                      matlab.ui.container.Menu
        YLabelMenu                      matlab.ui.container.Menu
        RESETMenu                       matlab.ui.container.Menu
        SpikeRatePlotMenu               matlab.ui.container.Menu
        SpikeRateoverTimeMenu           matlab.ui.container.Menu
        FontSizeMenu_2                  matlab.ui.container.Menu
        BarColorMenu                    matlab.ui.container.Menu
        BackgroundColorMenu             matlab.ui.container.Menu
        XLabelMenu_2                    matlab.ui.container.Menu
        YLabelMenu_2                    matlab.ui.container.Menu
        RESETMenu_2                     matlab.ui.container.Menu
        SpikeRateoverDepthMenu          matlab.ui.container.Menu
        FontSizeMenu_3                  matlab.ui.container.Menu
        BarColorMenu_2                  matlab.ui.container.Menu
        BackgroundColorMenu_2           matlab.ui.container.Menu
        XLabelMenu_3                    matlab.ui.container.Menu
        YLabelMenu_3                    matlab.ui.container.Menu
        RESETMenu_3                     matlab.ui.container.Menu
        SaveCustomeAppearanceMenu       matlab.ui.container.Menu
        DeleteCustomeAppearanceMenu     matlab.ui.container.Menu
        ExportDataMenu                  matlab.ui.container.Menu
        SpikeRateMenu                   matlab.ui.container.Menu
        AllSpikesMenu                   matlab.ui.container.Menu
        OverTimeMenu                    matlab.ui.container.Menu
        matMenu_5                       matlab.ui.container.Menu
        txtMenu_6                       matlab.ui.container.Menu
        xlsxMenu                        matlab.ui.container.Menu
        OverChannelMenu                 matlab.ui.container.Menu
        matMenu_6                       matlab.ui.container.Menu
        txtMenu_7                       matlab.ui.container.Menu
        xlsxMenu_4                      matlab.ui.container.Menu
        SelectedUnitMenu                matlab.ui.container.Menu
        matMenu_2                       matlab.ui.container.Menu
        txtMenu_3                       matlab.ui.container.Menu
        xlsxMenu_5                      matlab.ui.container.Menu
        MainPlotMenu                    matlab.ui.container.Menu
        AllSpikesMenu_2                 matlab.ui.container.Menu
        matMenu_3                       matlab.ui.container.Menu
        txtMenu_4                       matlab.ui.container.Menu
        xlsxMenu_2                      matlab.ui.container.Menu
        SelectedUnitMenu_2              matlab.ui.container.Menu
        matMenu_4                       matlab.ui.container.Menu
        txtMenu_5                       matlab.ui.container.Menu
        xlsxMenu_3                      matlab.ui.container.Menu
        Button_3                        matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        ChannelSelectionforPlottingEditField  matlab.ui.control.EditField
        ChannelSelectionforPlottingEditFieldLabel  matlab.ui.control.Label
        WaveformOptionsPanel            matlab.ui.container.Panel
        WaveformOptionsLabel            matlab.ui.control.Label
        WaveformSelectionforPlottingEditField  matlab.ui.control.EditField
        WaveformSelectionforPlottingEditFieldLabel  matlab.ui.control.Label
        CloseButton                     matlab.ui.control.Button
        SpikeAnalysisOptionsPanel       matlab.ui.container.Panel
        SpikeAnalysisOptionsLabel       matlab.ui.control.Label
        TimeWindowSpiketriggredLFPEditField  matlab.ui.control.EditField
        TimeWindowSpiketriggredLFPfromtoEditFieldLabel  matlab.ui.control.Label
        SpikeRateNumBinsEditField       matlab.ui.control.EditField
        SpikeRateNumBinsEditFieldLabel  matlab.ui.control.Label
        EventstoshowDropDown            matlab.ui.control.DropDown
        EventstoShowDropDownLabel       matlab.ui.control.Label
        ClustertoshowDropDown           matlab.ui.control.DropDown
        ClustertoShowDropDownLabel      matlab.ui.control.Label
        TypeofAnalysisDropDown          matlab.ui.control.DropDown
        TypeofAnalysisLabel             matlab.ui.control.Label
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_5                        matlab.ui.control.UIAxes
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
    end

    
    properties (Access = public)
        Mainapp = []; % Description
        numCluster = [];
        rgbMatrix = [];
        gwfparams = [];
        TwoORThreeD = "TwoD"; % standard 2d plots when possible
        AverageWaveforms = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            
            movegui(app.ContinuousSpikeAnalysisUIFigure, 'center');
            
            app.Mainapp = Mainapp;

            app = Utility_Change_Light_Dark_Mode(app,"ContSpikeAnalysis");
            
            if ~strcmp(app.Mainapp.Data.Info.SpikeType,"Internal")
                [Curation] = Spike_Module_Check_For_Curation(app.Mainapp.Data,app.Mainapp.Data.Info.SorterPath);
            else
                Curation = 0;
            end

            if strcmp(app.Mainapp.Data.Info.SpikeType,"Kilosort") && Curation == 0
                app.TypeofAnalysisDropDown.Items{1} = 'Spike Map';
                app.TypeofAnalysisDropDown.Items{2} = 'Spike Amplitude Density Along Depth';
                app.TypeofAnalysisDropDown.Items{3} = 'Cumulative Spike Amplitude Density Along Depth';
                app.TypeofAnalysisDropDown.Items{4} = 'Average Waveforms Across Channel';
                app.TypeofAnalysisDropDown.Items{5} = 'Spike Waveforms';
                app.TypeofAnalysisDropDown.Items{6} = 'Waveform Templates';
                app.TypeofAnalysisDropDown.Items{7} = 'Template from Max Amplitude Channel';
                app.TypeofAnalysisDropDown.Items{8} = 'Spike Triggered LFP';
            else
                app.TypeofAnalysisDropDown.Items{1} = 'Spike Map';
                app.TypeofAnalysisDropDown.Items{2} = 'Spike Amplitude Density Along Depth';
                app.TypeofAnalysisDropDown.Items{3} = 'Cumulative Spike Amplitude Density Along Depth';
                app.TypeofAnalysisDropDown.Items{4} = 'Average Waveforms Across Channel';
                app.TypeofAnalysisDropDown.Items{5} = 'Spike Waveforms';
                app.TypeofAnalysisDropDown.Items{6} = 'Spike Triggered LFP';
            end
            
            [app] = Spike_Module_Set_Up_Spike_Analysis_Windows(app,[],"Non");
            
            [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],app.Mainapp.Data.Info.SpikeType,app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.Mainapp.Data.Spikes.Waveforms,app.Mainapp.ActiveChannel,app.TypeofAnalysisDropDown.Value,app.Mainapp.PreservePlotChannelLocations);
            
            [~,app.Mainapp.CurrentPlotData] = Continous_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,PlotInfo,SpikePositions,SpikeAmps,SpikeTimes,Waveforms,WaveformChannel,CluterPositions,app.UIAxes,app.TypeofAnalysisDropDown.Value,app.TextArea,app.EventstoshowDropDown.Value,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,0,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations);
            
            app.UIAxes.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotBackgroundColor;
            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotFontSize;
            
            app.UIAxes_3.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRTimePlotBackgroundColor;
            app.UIAxes_3.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRTimePlotFontSize;

            app.UIAxes_5.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRChannelPlotBackgroundColor;
            app.UIAxes_5.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRChannelPlotFontSize;
            
            app.TextArea.Value{end+1} = ['Spike Type: ',convertStringsToChars(app.Mainapp.Data.Info.SpikeType)];
            
            if isfield(app.Mainapp.Data.Info,'Sorter')
                app.TextArea.Value{end+1} = ['Sorter: ',convertStringsToChars(app.Mainapp.Data.Info.Sorter)];
            end

            app.EventstoshowDropDown.Enable = "on";
            app.TimeWindowSpiketriggredLFPEditField.Enable = "on";
            app.WaveformSelectionforPlottingEditField.Enable = "on";
            app.ChannelSelectionforPlottingEditField.Enable = "on";
            
            app.UIAxes.YColor = 'k';  
            %app.UIAxes.YTickLabelMode = 'auto';
            app.UIAxes.XColor = 'k';  
            app.UIAxes.Title.Color = 'k';  
            %app.UIAxes.YTickLabelMode = 'auto';
            
        end

        % Value changed function: ClustertoshowDropDown
        function ClustertoshowDropDownValueChanged(app, event)
            %% Prepare and Initiate Plots
            % Reset Spike Rate plot in order to show new bin size
            yyaxis(app.UIAxes_3, 'left');
            app.UIAxes_3.NextPlot = "replace"; 
            plot(app.UIAxes_3,0,0);
            app.UIAxes_3.NextPlot = "add";
            set(app.UIAxes_3, 'YDir','reverse');
            app.UIAxes_3.FontSize = 10;
            ylabel(app.UIAxes_3,"Spike Rate [Hz]")

            yyaxis(app.UIAxes_3, 'right');
            app.UIAxes_3.NextPlot = "replace"; 
            plot(app.UIAxes_3,0,0);
            app.UIAxes_3.NextPlot = "add";
            set(app.UIAxes_3, 'YDir','reverse');
            ylabel(app.UIAxes_3,"Spike Rate [Hz]")
            app.UIAxes_3.FontSize = 10;

            app.UIAxes_3.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRTimePlotBackgroundColor;
            app.UIAxes_3.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRTimePlotFontSize;

            % delte saved unit specific data if no unit selected
            if strcmp(app.ClustertoshowDropDown.Value,"All") || strcmp(app.ClustertoshowDropDown.Value,"Non")
                if isfield(app.Mainapp.CurrentPlotData,'MainRateUnitXData')
                    app.Mainapp.CurrentPlotData = rmfield(app.Mainapp.CurrentPlotData, {'MainRateUnitXData', 'MainRateUnitYData','MainRateUnitCData', 'MainRateUnitType', 'MainRateUnitXTicks'});
                end
                if isfield(app.Mainapp.CurrentPlotData,'MainUnitXData')
                    app.Mainapp.CurrentPlotData = rmfield(app.Mainapp.CurrentPlotData, {'MainUnitXData', 'MainUnitYData','MainUnitCData', 'MainUnitType', 'MainUnitXTicks'});
                end
            end

            if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel") && isempty(app.AverageWaveforms)
                % For Kilosort we dont have channel information to extract from raw or
                % preprocessed data --> Therefor we take channel closest to position
                [app.AverageWaveforms,~] = Spikes_Module_Get_Waveforms(app.Mainapp.Data,app.Mainapp.Data.Spikes.SpikeTimes,app.Mainapp.Data.Spikes.SpikeChannel,"AverageWaveforms");
            end
            
            if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel")
                [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],app.Mainapp.Data.Info.SpikeType,app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.AverageWaveforms,app.Mainapp.ActiveChannel,app.TypeofAnalysisDropDown.Value,app.Mainapp.PreservePlotChannelLocations);
            else
                [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],app.Mainapp.Data.Info.SpikeType,app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.Mainapp.Data.Spikes.Waveforms,app.Mainapp.ActiveChannel,app.TypeofAnalysisDropDown.Value,app.Mainapp.PreservePlotChannelLocations);
            end
            
            [TempData,app.Mainapp.CurrentPlotData] = Continous_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,PlotInfo,SpikePositions,SpikeAmps,SpikeTimes,Waveforms,WaveformChannel,CluterPositions,app.UIAxes,app.TypeofAnalysisDropDown.Value,app.TextArea,app.EventstoshowDropDown.Value,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,0,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations);
           
            if ~strcmp(app.TypeofAnalysisDropDown.Value,"Spike Map") 
               [~,~] = Continous_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,PlotInfo,SpikePositions,SpikeAmps,SpikeTimes,Waveforms,WaveformChannel,CluterPositions,app.UIAxes,"SpikeRateBinSizeChange",app.TextArea,app.EventstoshowDropDown.Value,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,0,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations);
            end

            if strcmp(app.TypeofAnalysisDropDown.Value,"Spike Triggered LFP")
                if ~isempty(TempData)
                    app.Mainapp.Data = TempData;
                    PreviousChannelDeletetion = 0;
                    % If Channel were deleted in previous preprocessing flag this
                    % to reset main window parameter after + only allow for prepro
                    % view if prepro was generated
                    if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                        PreviousChannelDeletetion = 1;
                    end
                    %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                    Utility_Show_Info_Loaded_Data(app.Mainapp);
                    %% Wrap up preprocessing
                    [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                    if strcmp(app.Mainapp.DropDown.Value,'Preprocessed Data')
                        if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                            TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                            % Calculate the absolute differences
                            differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                            
                            % Find the index of the minimum difference
                            [~, app.Mainapp.CurrentTimePoints] = min(differences);
                        end
                    end
                    %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                    % Plot functions are fully autonomous without needed the app
                    % object. It is only needed to get the necessary parameter.
                    % Both is combined in one function for convenience.
                    %input 2: 1 if plot time, 0 if no time plot necessary
                    %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                    %input 4: Whether Data plot should run in a movie or not
                    Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
                end
             else
                app.Mainapp.Data = TempData;
            end

            if strcmp(app.TypeofAnalysisDropDown.Value,"Spike Amplitude Density Along Depth") || strcmp(app.TypeofAnalysisDropDown.Value,"Cumulative Spike Amplitude Density Along Depth") || strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel") || strcmp(app.TypeofAnalysisDropDown.Value,"Spike Triggered LFP")
                cb=colorbar('peer',app.UIAxes,'location','WestOutside');
                cb.Color = 'k';              % Sets tick mark and label color to black
                cb.Label.Rotation = 270;
                cb.Label.Color = 'k';        % Sets the color of the label text

                if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel")
                    cb.Label.String = "Amplitude [mV]";
                elseif strcmp(app.TypeofAnalysisDropDown.Value,"Spike Triggered LFP")
                    cb.Label.String = "Signal [mV]";
                else
                    cb.Label.String = "Firing Rate [Hz]";
                end
            end
            
            if strcmp(app.TypeofAnalysisDropDown.Value,"Spike Triggered LFP") || strcmp(app.TypeofAnalysisDropDown.Value,"Spike Triggered Average")
                app.UIAxes.YColor = 'k';  
                %app.UIAxes.YTickLabelMode = 'auto';
                app.UIAxes.Title.Color = 'k';  
            end

            if strcmp(app.TypeofAnalysisDropDown.Value,"Spike Map") && strcmp(app.ClustertoshowDropDown.Value,'All')  
                colorbar(app.UIAxes,'off');
            end
            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.Mainapp.ConKilosortSpikesWindow = [];

            if ~isempty(app.Mainapp.ProbeViewWindowHandle) % delete option from probe view 
                for i = 1:length(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                    if strcmp(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Cont. Spike Analysis')
                        app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items(i) = [];
                        break;
                    end
                end
            else % if no probe view available
 
            end

            delete(app);
        end

        % Value changed function: EventstoshowDropDown
        function EventstoshowDropDownValueChanged(app, event)
            [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],app.Mainapp.Data.Info.SpikeType,app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.Mainapp.Data.Spikes.Waveforms,app.Mainapp.ActiveChannel,app.TypeofAnalysisDropDown.Value,app.Mainapp.PreservePlotChannelLocations);
            
            [~,app.Mainapp.CurrentPlotData] = Continous_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,PlotInfo,SpikePositions,SpikeAmps,SpikeTimes,Waveforms,WaveformChannel,CluterPositions,app.UIAxes,app.TypeofAnalysisDropDown.Value,app.TextArea,app.EventstoshowDropDown.Value,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,0,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations);
            
            if strcmp(app.TypeofAnalysisDropDown.Value,"Spike Amplitude Density Along Depth") || strcmp(app.TypeofAnalysisDropDown.Value,"Cumulative Spike Amplitude Density Along Depth") || strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel") || strcmp(app.TypeofAnalysisDropDown.Value,"Spike Triggered LFP")
                cb=colorbar('peer',app.UIAxes,'location','WestOutside');
                cb.Color = 'k';              % Sets tick mark and label color to black
                cb.Label.Rotation = 270;
                cb.Label.Color = 'k';        % Sets the color of the label text

                if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel")
                    cb.Label.String = "Amplitude [mV]";
                elseif strcmp(app.TypeofAnalysisDropDown.Value,"Spike Triggered LFP")
                    cb.Label.String = "Signal [mV]";
                else
                    cb.Label.String = "Firing Rate [Hz]";
                end
            end
            
            if strcmp(app.TypeofAnalysisDropDown.Value,"Spike Triggered LFP") || strcmp(app.TypeofAnalysisDropDown.Value,"Spike Triggered Average")
                app.UIAxes.YColor = 'k';  
                %app.UIAxes.YTickLabelMode = 'auto';
                app.UIAxes.Title.Color = 'k';  
            end

            if strcmp(app.TypeofAnalysisDropDown.Value,"Spike Map") && strcmp(app.ClustertoshowDropDown.Value,'All')  
                colorbar(app.UIAxes,'off');
            end
        end

        % Value changed function: SpikeRateNumBinsEditField
        function SpikeRateNumBinsEditFieldValueChanged(app, event)
            %% Prepare and Initiate Plots
            % Reset Spike Rate plot in order to show new bin size
            yyaxis(app.UIAxes_3, 'left');
            app.UIAxes_3.NextPlot = "replace"; 
            plot(app.UIAxes_3,0,0);
            app.UIAxes_3.NextPlot = "add";
            set(app.UIAxes_3, 'YDir','reverse');
            app.UIAxes_3.FontSize = 10;
            ylabel(app.UIAxes_3,"Spike Rate [Hz]")

            yyaxis(app.UIAxes_3, 'right');
            app.UIAxes_3.NextPlot = "replace"; 
            plot(app.UIAxes_3,0,0);
            app.UIAxes_3.NextPlot = "add";
            set(app.UIAxes_3, 'YDir','reverse');
            ylabel(app.UIAxes_3,"Spike Rate [Hz]")
            app.UIAxes_3.FontSize = 10;

            app.UIAxes_3.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRTimePlotBackgroundColor;
            app.UIAxes_3.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRTimePlotFontSize;

            app.UIAxes_5.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRChannelPlotBackgroundColor;
            app.UIAxes_5.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRChannelPlotFontSize;

            [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],app.Mainapp.Data.Info.SpikeType,app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.Mainapp.Data.Spikes.Waveforms,app.Mainapp.ActiveChannel,app.TypeofAnalysisDropDown.Value,app.Mainapp.PreservePlotChannelLocations);
            
            [~,app.Mainapp.CurrentPlotData] = Continous_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,PlotInfo,SpikePositions,SpikeAmps,SpikeTimes,Waveforms,WaveformChannel,CluterPositions,app.UIAxes,'SpikeRateBinSizeChange',app.TextArea,app.EventstoshowDropDown.Value,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,0,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations);
            
            if strcmp(app.TypeofAnalysisDropDown.Value,"Spike Amplitude Density Along Depth") || strcmp(app.TypeofAnalysisDropDown.Value,"Cumulative Spike Amplitude Density Along Depth") || strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel") || strcmp(app.TypeofAnalysisDropDown.Value,"Spike Triggered LFP")
                cb=colorbar('peer',app.UIAxes,'location','WestOutside');
                cb.Color = 'k';              % Sets tick mark and label color to black
                cb.Label.Rotation = 270;
                cb.Label.Color = 'k';        % Sets the color of the label text

                if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel")
                    cb.Label.String = "Amplitude [mV]";
                elseif strcmp(app.TypeofAnalysisDropDown.Value,"Spike Triggered LFP")
                    cb.Label.String = "Signal [mV]";
                else
                    cb.Label.String = "Firing Rate [Hz]";
                end
            end
            
            if strcmp(app.TypeofAnalysisDropDown.Value,"Spike Triggered LFP") || strcmp(app.TypeofAnalysisDropDown.Value,"Spike Triggered Average")
                app.UIAxes.YColor = 'k';  
                %app.UIAxes.YTickLabelMode = 'auto';
                app.UIAxes.Title.Color = 'k';  
            end

            if strcmp(app.TypeofAnalysisDropDown.Value,"Spike Map") && strcmp(app.ClustertoshowDropDown.Value,'All')  
                colorbar(app.UIAxes,'off');
            end
        end

        % Menu selected function: PNGMenu
        function PNGMenuSelected(app, event)
            [file, path] = uiputfile('*.png', 'Save as');

            exportapp(app.ContinuousSpikeAnalysisUIFigure,fullfile(path,file)) 
        end

        % Menu selected function: JPGMenu
        function JPGMenuSelected(app, event)
            [file, path] = uiputfile('*.jpg', 'Save as');

            exportapp(app.ContinuousSpikeAnalysisUIFigure,fullfile(path,file)) 
        end

        % Value changed function: TypeofAnalysisDropDown
        function TypeofAnalysisDropDownValueChanged(app, event)
            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";

            app.UIAxes.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotBackgroundColor;
            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotFontSize;
            
            if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel") && isempty(app.AverageWaveforms)
                %% Data needs to be high pass filtered! Otherwise waveforms are weird. Recommended is also grand average
                % Detect high pass filter
                HigPassFiltered = 1;
                
                if isfield(app.Mainapp.Data,'Preprocessed') 
                    if isfield(app.Mainapp.Data.Info,'FilterMethod') 
                        if ~strcmp(app.Mainapp.Data.Info.FilterMethod,'High-Pass')
                            msgbox("Warning: No high-pass filtered data found. This will screw with scale and amplitude of waveforms. Data is temporarily high pass filtered for waveform extraction!");
                            HigPassFiltered = 0;
                        end
                    else
                        msgbox("Warning: No high-pass filtered data found. This will screw with scale and amplitude of waveforms. Data is temporarily high pass filtered for waveform extraction!");
                        HigPassFiltered = 0;
                    end
                else
                    msgbox("Warning: No high-pass filtered data found. This will screw with scale and amplitude of waveforms. Data is temporarily high pass filtered for waveform extraction!");
                    HigPassFiltered = 0;
                end

                %%% if it has to be high pass filtered --> save as TempData, extract
                %%% waveforms and delete temp variable
                if HigPassFiltered == 0
                    HighPassFilterSettings = [];
                    Spike_Extraction_HighPassWindow = Spike_Extraction_AskforHighPass(HighPassFilterSettings);
                    
                    uiwait(Spike_Extraction_HighPassWindow.PreproSTAWindowUIFigure);
                    
                    if isvalid(Spike_Extraction_HighPassWindow)
                        Cutoff = Spike_Extraction_HighPassWindow.HighPassFilterSettings.Cutoff;
                        FilterOrder = Spike_Extraction_HighPassWindow.HighPassFilterSettings.FilterOrder;
                        SaveFilter = Spike_Extraction_HighPassWindow.HighPassFilterSettings.SaveFilter;
                        delete(Spike_Extraction_HighPassWindow);
                    else
                        msgbox("Preprocessing canceled, average waveforms not computed.")
                        return;
                    end
                
                    PreproInfo = [];
                    PreprocessingSteps = [];
                
                    Methods = ["Filter"];
                    [PreproInfo,PreprocessingSteps,~] = Preprocess_Module_Construct_Pipeline(Methods,PreproInfo,PreprocessingSteps,0,"High-Pass","Butterworth IR",Cutoff,"Zero-phase forward and reverse",FilterOrder,[],app.Mainapp.Data.Info.NativeSamplingRate);
                        
                    if isfield(PreproInfo,'ChannelDeletion')
                        ChannelDeletion = PreproInfo.ChannelDeletion;
                    else
                        ChannelDeletion = [];
                    end
                    
                    PlaceholderTextArea = [];
                    
                    if strcmp(SaveFilter,"No")
                        [TempData,PreproInfo,PlaceholderTextArea] = Preprocess_Module_Delete_Old_Settings(app.Mainapp.Data,PreproInfo,PreprocessingSteps,ChannelDeletion,PlaceholderTextArea);
                        [TempData] = Preprocess_Module_Apply_Pipeline(TempData,TempData.Info.NativeSamplingRate,PreprocessingSteps,0,PreproInfo,ChannelDeletion,PlaceholderTextArea,app.Mainapp.ParallelPool);
                        
                        %% Now extract Waveforms
                        % For Kilosort we dont have channel information to extract from raw or
                        % preprocessed data --> Therefor we take channel closest to position

                        [app.AverageWaveforms,~] = Spikes_Module_Get_Waveforms(TempData,TempData.Spikes.SpikeTimes,app.Mainapp.Data.Spikes.SpikeChannel,"AverageWaveforms");
                        clear TempData;
                    else
                        [app.Mainapp.Data,PreproInfo,PlaceholderTextArea] = Preprocess_Module_Delete_Old_Settings(app.Mainapp.Data,PreproInfo,PreprocessingSteps,ChannelDeletion,PlaceholderTextArea);
                        [app.Mainapp.Data] = Preprocess_Module_Apply_Pipeline(app.Mainapp.Data,app.Mainapp.Data.Info.NativeSamplingRate,PreprocessingSteps,0,PreproInfo,ChannelDeletion,PlaceholderTextArea,app.Mainapp.ParallelPool);
                
                        %% Now extract Waveforms
                        % For Kilosort we dont have channel information to extract from raw or
                        % preprocessed data --> Therefor we take channel closest to position
                        [app.AverageWaveforms,~] = Spikes_Module_Get_Waveforms(app.Mainapp.Data,app.Mainapp.Data.Spikes.SpikeTimes,app.Mainapp.Data.Spikes.SpikeChannel,"AverageWaveforms");

                        Utility_Show_Info_Loaded_Data(app.Mainapp);
            
                        if strcmp(SaveFilter,"Yes")
                            [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],[]);
                        end
            
                        Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
                    end
                else % If high pass was already applied
 
                    [app.AverageWaveforms,~] = Spikes_Module_Get_Waveforms(app.Mainapp.Data,app.Mainapp.Data.Spikes.SpikeTimes,app.Mainapp.Data.Spikes.SpikeChannel,"AverageWaveforms");
                end
            end
            
            if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel")
                [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],app.Mainapp.Data.Info.SpikeType,app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.AverageWaveforms,app.Mainapp.ActiveChannel,app.TypeofAnalysisDropDown.Value,app.Mainapp.PreservePlotChannelLocations);
            else
                [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],app.Mainapp.Data.Info.SpikeType,app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.Mainapp.Data.Spikes.Waveforms,app.Mainapp.ActiveChannel,app.TypeofAnalysisDropDown.Value,app.Mainapp.PreservePlotChannelLocations);
            end

            [TempData,app.Mainapp.CurrentPlotData] = Continous_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,PlotInfo,SpikePositions,SpikeAmps,SpikeTimes,Waveforms,WaveformChannel,CluterPositions,app.UIAxes,app.TypeofAnalysisDropDown.Value,app.TextArea,app.EventstoshowDropDown.Value,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,0,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations);
           
            %% Manage which GUI elements are active
            if strcmp(app.TypeofAnalysisDropDown.Value,"Spike Map")
                app.EventstoshowDropDown.Enable = "on";
                app.TimeWindowSpiketriggredLFPEditField.Enable = "on";
                app.WaveformSelectionforPlottingEditField.Enable = "on";
                app.ChannelSelectionforPlottingEditField.Enable = "on";
            elseif strcmp(app.TypeofAnalysisDropDown.Value,"Spike Amplitude Density Along Depth")
                app.EventstoshowDropDown.Enable = "on";
                app.TimeWindowSpiketriggredLFPEditField.Enable = "on";
                app.WaveformSelectionforPlottingEditField.Enable = "on";
                app.ChannelSelectionforPlottingEditField.Enable = "on";
            elseif strcmp(app.TypeofAnalysisDropDown.Value,"Cumulative Spike Amplitude Density Along Depth")
                app.EventstoshowDropDown.Enable = "on";
                app.TimeWindowSpiketriggredLFPEditField.Enable = "on";
                app.WaveformSelectionforPlottingEditField.Enable = "on";
                app.ChannelSelectionforPlottingEditField.Enable = "on";
            elseif strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel")
                app.EventstoshowDropDown.Enable = "on";
                app.TimeWindowSpiketriggredLFPEditField.Enable = "on";
                app.WaveformSelectionforPlottingEditField.Enable = "on";
                app.ChannelSelectionforPlottingEditField.Enable = "on";
            elseif strcmp(app.TypeofAnalysisDropDown.Value,"Waveforms from Raw Data")
                app.EventstoshowDropDown.Enable = "on";
                app.TimeWindowSpiketriggredLFPEditField.Enable = "on";
                app.WaveformSelectionforPlottingEditField.Enable = "on";
                app.ChannelSelectionforPlottingEditField.Enable = "on";
            elseif strcmp(app.TypeofAnalysisDropDown.Value,"Waveforms Templates")
                app.EventstoshowDropDown.Enable = "on";
                app.TimeWindowSpiketriggredLFPEditField.Enable = "on";
                app.WaveformSelectionforPlottingEditField.Enable = "on";
                app.ChannelSelectionforPlottingEditField.Enable = "on";
            elseif strcmp(app.TypeofAnalysisDropDown.Value,"Template from Max Amplitude Channel")
                app.EventstoshowDropDown.Enable = "on";
                app.TimeWindowSpiketriggredLFPEditField.Enable = "on";
                app.WaveformSelectionforPlottingEditField.Enable = "on";
                app.ChannelSelectionforPlottingEditField.Enable = "on";
            elseif strcmp(app.TypeofAnalysisDropDown.Value,"Spike Triggered LFP")
                app.EventstoshowDropDown.Enable = "on";
                app.TimeWindowSpiketriggredLFPEditField.Enable = "on";
                app.WaveformSelectionforPlottingEditField.Enable = "on";
                app.ChannelSelectionforPlottingEditField.Enable = "on";
            end

            if strcmp(app.TypeofAnalysisDropDown.Value,"Spike Triggered LFP")
                if ~isempty(TempData)
                    app.Mainapp.Data = TempData;
                    PreviousChannelDeletetion = 0;
                    % If Channel were deleted in previous preprocessing flag this
                    % to reset main window parameter after + only allow for prepro
                    % view if prepro was generated
                    if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                        PreviousChannelDeletetion = 1;
                    end
                    %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                    Utility_Show_Info_Loaded_Data(app.Mainapp);
                    %% Wrap up preprocessing
                    [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                    if strcmp(app.Mainapp.DropDown.Value,'Preprocessed Data')
                        if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                            TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                            % Calculate the absolute differences
                            differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                            
                            % Find the index of the minimum difference
                            [~, app.Mainapp.CurrentTimePoints] = min(differences);
                        end
                    end
                    %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                    % Plot functions are fully autonomous without needed the app
                    % object. It is only needed to get the necessary parameter.
                    % Both is combined in one function for convenience.
                    %input 2: 1 if plot time, 0 if no time plot necessary
                    %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                    %input 4: Whether Data plot should run in a movie or not
                    Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
                end
            else
                app.Mainapp.Data = TempData;
            end
            
            if strcmp(app.TypeofAnalysisDropDown.Value,"Spike Amplitude Density Along Depth") || strcmp(app.TypeofAnalysisDropDown.Value,"Cumulative Spike Amplitude Density Along Depth") || strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel") || strcmp(app.TypeofAnalysisDropDown.Value,"Spike Triggered LFP")
                cb=colorbar('peer',app.UIAxes,'location','WestOutside');
                cb.Color = 'k';              % Sets tick mark and label color to black
                cb.Label.Rotation = 270;
                cb.Label.Color = 'k';        % Sets the color of the label text

                if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel")
                    cb.Label.String = "Amplitude [mV]";
                elseif strcmp(app.TypeofAnalysisDropDown.Value,"Spike Triggered LFP")
                    cb.Label.String = "Signal [mV]";
                else
                    cb.Label.String = "Firing Rate [Hz]";
                end
            end

            
            app.UIAxes.YColor = 'k';  
            %app.UIAxes.YTickLabelMode = 'auto';
            app.UIAxes.XColor = 'k';  
            app.UIAxes.Title.Color = 'k';  
            %app.UIAxes.YTickLabelMode = 'auto';
            if strcmp(app.TypeofAnalysisDropDown.Value,"Spike Map")
                % Disable XTick labels
                app.UIAxes.XTickLabel = [];
                app.UIAxes.Title.Color = 'k'; 
                % Remove xlabel
                app.UIAxes.XLabel.String = '';
                app.UIAxes.YColor = 'k';  
                %app.UIAxes.YTickLabelMode = 'auto';
            end

            if strcmp(app.TypeofAnalysisDropDown.Value,"Spike Map") && strcmp(app.ClustertoshowDropDown.Value,'All')  
                colorbar(app.UIAxes,'off');
            end

            app.Mainapp = Utility_Change_Light_Dark_Mode(app.Mainapp,"MainWindow");

        end

        % Value changed function: WaveformSelectionforPlottingEditField
        function WaveformSelectionforPlottingEditFieldValueChanged(app, event)
            if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel") && isempty(app.AverageWaveforms)
                % For Kilosort we dont have channel information to extract from raw or
                % preprocessed data --> Therefor we take channel closest to position
                [app.AverageWaveforms,~] = Spikes_Module_Get_Waveforms(app.Mainapp.Data,app.Mainapp.Data.Spikes.SpikeTimes,app.Mainapp.Data.Spikes.SpikeChannel,"AverageWaveforms");
            end
            
            if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel")
                [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],app.Mainapp.Data.Info.SpikeType,app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.AverageWaveforms,app.Mainapp.ActiveChannel,app.TypeofAnalysisDropDown.Value,app.Mainapp.PreservePlotChannelLocations);
            else
                [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],app.Mainapp.Data.Info.SpikeType,app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.Mainapp.Data.Spikes.Waveforms,app.Mainapp.ActiveChannel,app.TypeofAnalysisDropDown.Value,app.Mainapp.PreservePlotChannelLocations);
            end

            if ~strcmp(app.TypeofAnalysisDropDown.Value,"Spike Triggered LFP")
                [~,app.Mainapp.CurrentPlotData] = Continous_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,PlotInfo,SpikePositions,SpikeAmps,SpikeTimes,Waveforms,WaveformChannel,CluterPositions,app.UIAxes,app.TypeofAnalysisDropDown.Value,app.TextArea,app.EventstoshowDropDown.Value,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,0,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations);
            end

            if strcmp(app.TypeofAnalysisDropDown.Value,"Spike Amplitude Density Along Depth") || strcmp(app.TypeofAnalysisDropDown.Value,"Cumulative Spike Amplitude Density Along Depth") || strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel") || strcmp(app.TypeofAnalysisDropDown.Value,"Spike Triggered LFP")
                cb=colorbar('peer',app.UIAxes,'location','WestOutside');
                cb.Color = 'k';              % Sets tick mark and label color to black
                cb.Label.Rotation = 270;
                cb.Label.Color = 'k';        % Sets the color of the label text

                if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel")
                    cb.Label.String = "Amplitude [mV]";
                elseif strcmp(app.TypeofAnalysisDropDown.Value,"Spike Triggered LFP")
                    cb.Label.String = "Signal [mV]";
                else
                    cb.Label.String = "Firing Rate [Hz]";
                end
            end
            
            if strcmp(app.TypeofAnalysisDropDown.Value,"Spike Triggered LFP") || strcmp(app.TypeofAnalysisDropDown.Value,"Spike Triggered Average")
                app.UIAxes.YColor = 'k';  
                %app.UIAxes.YTickLabelMode = 'auto';
                app.UIAxes.Title.Color = 'k';  
            end

            if strcmp(app.TypeofAnalysisDropDown.Value,"Spike Map") && strcmp(app.ClustertoshowDropDown.Value,'All')  
                colorbar(app.UIAxes,'off');
            end
        end

        % Value changed function: ChannelSelectionforPlottingEditField
        function ChannelSelectionforPlottingEditFieldValueChanged(app, event)
              [app.ChannelSelectionforPlottingEditField.Value] = Utility_SimpleCheckInputs(app.ChannelSelectionforPlottingEditField.Value,"Two",strcat('1,',num2str(size(app.Mainapp.Data.Raw,1))),1,0);
              
                if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel") && isempty(app.AverageWaveforms)
                    % For Kilosort we dont have channel information to extract from raw or
                    % preprocessed data --> Therefor we take channel closest to position
                    [app.AverageWaveforms,~] = Spikes_Module_Get_Waveforms(app.Mainapp.Data,app.Mainapp.Data.Spikes.SpikeTimes,app.Mainapp.Data.Spikes.SpikeChannel,"AverageWaveforms");
                end
                
                if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel")
                    [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],app.Mainapp.Data.Info.SpikeType,app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.AverageWaveforms,app.Mainapp.ActiveChannel,app.TypeofAnalysisDropDown.Value,app.Mainapp.PreservePlotChannelLocations);
                else
                    [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],app.Mainapp.Data.Info.SpikeType,app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.Mainapp.Data.Spikes.Waveforms,app.Mainapp.ActiveChannel,app.TypeofAnalysisDropDown.Value,app.Mainapp.PreservePlotChannelLocations);
                end

                if ~strcmp(app.TypeofAnalysisDropDown.Value,"Spike Map") 
                    [~,~] = Continous_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,PlotInfo,SpikePositions,SpikeAmps,SpikeTimes,Waveforms,WaveformChannel,CluterPositions,app.UIAxes,"SpikeRateBinSizeChange",app.TextArea,app.EventstoshowDropDown.Value,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,0,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations);
                end

              [TempData,app.Mainapp.CurrentPlotData] = Continous_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,PlotInfo,SpikePositions,SpikeAmps,SpikeTimes,Waveforms,WaveformChannel,CluterPositions,app.UIAxes,app.TypeofAnalysisDropDown.Value,app.TextArea,app.EventstoshowDropDown.Value,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,0,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations);
           
              if strcmp(app.TypeofAnalysisDropDown.Value,"Spike Triggered LFP")
                if ~isempty(TempData)
                    app.Mainapp.Data = TempData;
                    PreviousChannelDeletetion = 0;
                    % If Channel were deleted in previous preprocessing flag this
                    % to reset main window parameter after + only allow for prepro
                    % view if prepro was generated
                    if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                        PreviousChannelDeletetion = 1;
                    end
                    %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                    Utility_Show_Info_Loaded_Data(app.Mainapp);
                    %% Wrap up preprocessing
                    [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                    if strcmp(app.Mainapp.DropDown.Value,'Preprocessed Data')
                        if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                            TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                            % Calculate the absolute differences
                            differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                            
                            % Find the index of the minimum difference
                            [~, app.Mainapp.CurrentTimePoints] = min(differences);
                        end
                    end
                    %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                    % Plot functions are fully autonomous without needed the app
                    % object. It is only needed to get the necessary parameter.
                    % Both is combined in one function for convenience.
                    %input 2: 1 if plot time, 0 if no time plot necessary
                    %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                    %input 4: Whether Data plot should run in a movie or not
                    Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
                end
             else
                app.Mainapp.Data = TempData;
             end
        end

        % Value changed function: TimeWindowSpiketriggredLFPEditField
        function TimeWindowSpiketriggredLFPEditFieldValueChanged(app, event)
            [app.TimeWindowSpiketriggredLFPEditField.Value] = Utility_SimpleCheckInputs(app.TimeWindowSpiketriggredLFPEditField.Value,"Two",strcat('-0.005,0.2'),0,0);

            [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],app.Mainapp.Data.Info.SpikeType,app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.Mainapp.Data.Spikes.Waveforms,app.Mainapp.ActiveChannel,app.TypeofAnalysisDropDown.Value,app.Mainapp.PreservePlotChannelLocations);
            
            [TempData,app.Mainapp.CurrentPlotData] = Continous_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,PlotInfo,SpikePositions,SpikeAmps,SpikeTimes,Waveforms,WaveformChannel,CluterPositions,app.UIAxes,app.TypeofAnalysisDropDown.Value,app.TextArea,app.EventstoshowDropDown.Value,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,0,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations);
           
            if strcmp(app.TypeofAnalysisDropDown.Value,"Spike Triggered LFP")
                if ~isempty(TempData)
                    app.Mainapp.Data = TempData;
                    PreviousChannelDeletetion = 0;
                    % If Channel were deleted in previous preprocessing flag this
                    % to reset main window parameter after + only allow for prepro
                    % view if prepro was generated
                    if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                        PreviousChannelDeletetion = 1;
                    end
                    %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                    Utility_Show_Info_Loaded_Data(app.Mainapp);
                    %% Wrap up preprocessing
                    [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                    if strcmp(app.Mainapp.DropDown.Value,'Preprocessed Data')
                        if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                            TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                            % Calculate the absolute differences
                            differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                            
                            % Find the index of the minimum difference
                            [~, app.Mainapp.CurrentTimePoints] = min(differences);
                        end
                    end
                    %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                    % Plot functions are fully autonomous without needed the app
                    % object. It is only needed to get the necessary parameter.
                    % Both is combined in one function for convenience.
                    %input 2: 1 if plot time, 0 if no time plot necessary
                    %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                    %input 4: Whether Data plot should run in a movie or not
                    Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
                end
             else
                app.Mainapp.Data = TempData;
            end

            if strcmp(app.TypeofAnalysisDropDown.Value,"Spike Amplitude Density Along Depth") || strcmp(app.TypeofAnalysisDropDown.Value,"Cumulative Spike Amplitude Density Along Depth") || strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel") || strcmp(app.TypeofAnalysisDropDown.Value,"Spike Triggered LFP")
                cb=colorbar('peer',app.UIAxes,'location','WestOutside');
                cb.Color = 'k';              % Sets tick mark and label color to black
                cb.Label.Rotation = 270;
                cb.Label.Color = 'k';        % Sets the color of the label text

                if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel")
                    cb.Label.String = "Amplitude [mV]";
                elseif strcmp(app.TypeofAnalysisDropDown.Value,"Spike Triggered LFP")
                    cb.Label.String = "Signal [mV]";
                else
                    cb.Label.String = "Firing Rate [Hz]";
                end
            end

            app.UIAxes.YColor = 'k';  
            %pp.UIAxes.YTickLabelMode = 'auto';
            app.UIAxes.XColor = 'k';  
            app.UIAxes.Title.Color = 'k';  
            %app.UIAxes.YTickLabelMode = 'auto';

            if strcmp(app.TypeofAnalysisDropDown.Value,"Spike Map")
                % Disable XTick labels
                app.UIAxes.XTickLabel = [];
                app.UIAxes.Title.Color = 'k'; 
                % Remove xlabel
                app.UIAxes.XLabel.String = '';
                app.UIAxes.YColor = 'k';  
                %app.UIAxes.YTickLabelMode = 'auto';
            end

            if strcmp(app.TypeofAnalysisDropDown.Value,"Spike Map") && strcmp(app.ClustertoshowDropDown.Value,'All')  
                colorbar(app.UIAxes,'off');
            end
        end

        % Menu selected function: Show2DimensionalPlotsMenu
        function Show2DimensionalPlotsMenuSelected(app, event)
            app.TwoORThreeD = "TwoD";

            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";
            set(app.UIAxes, 'YDir','reverse');
            set(app.UIAxes,'xticklabel',{[]});

            app.UIAxes.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotBackgroundColor;
            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotFontSize;
            

            if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel") && isempty(app.AverageWaveforms)
                % For Kilosort we dont have channel information to extract from raw or
                % preprocessed data --> Therefor we take channel closest to position
                [app.AverageWaveforms,~] = Spikes_Module_Get_Waveforms(app.Mainapp.Data,app.Mainapp.Data.Spikes.SpikeTimes,app.Mainapp.Data.Spikes.SpikeChannel,"AverageWaveforms");
            end
            
            if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel")
                [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],app.Mainapp.Data.Info.SpikeType,app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.AverageWaveforms,app.Mainapp.ActiveChannel,app.TypeofAnalysisDropDown.Value,app.Mainapp.PreservePlotChannelLocations);
            else
                [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],app.Mainapp.Data.Info.SpikeType,app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.Mainapp.Data.Spikes.Waveforms,app.Mainapp.ActiveChannel,app.TypeofAnalysisDropDown.Value,app.Mainapp.PreservePlotChannelLocations);
            end
            
            [TempData,app.Mainapp.CurrentPlotData] = Continous_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,PlotInfo,SpikePositions,SpikeAmps,SpikeTimes,Waveforms,WaveformChannel,CluterPositions,app.UIAxes,app.TypeofAnalysisDropDown.Value,app.TextArea,app.EventstoshowDropDown.Value,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,0,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations);
           
            if strcmp(app.TypeofAnalysisDropDown.Value,"Spike Triggered LFP")
                if ~isempty(TempData)
                    app.Mainapp.Data = TempData;
                    PreviousChannelDeletetion = 0;
                    % If Channel were deleted in previous preprocessing flag this
                    % to reset main window parameter after + only allow for prepro
                    % view if prepro was generated
                    if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                        PreviousChannelDeletetion = 1;
                    end
                    %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                    Utility_Show_Info_Loaded_Data(app.Mainapp);
                    %% Wrap up preprocessing
                    [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                    if strcmp(app.Mainapp.DropDown.Value,'Preprocessed Data')
                        if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                            TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                            % Calculate the absolute differences
                            differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                            
                            % Find the index of the minimum difference
                            [~, app.Mainapp.CurrentTimePoints] = min(differences);
                        end
                    end
                    %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                    % Plot functions are fully autonomous without needed the app
                    % object. It is only needed to get the necessary parameter.
                    % Both is combined in one function for convenience.
                    %input 2: 1 if plot time, 0 if no time plot necessary
                    %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                    %input 4: Whether Data plot should run in a movie or not
                    Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
                end
             else
                app.Mainapp.Data = TempData;
             end
        end

        % Menu selected function: SpikeRateMenu
        function SpikeRateMenuSelected(app, event)
            
        end

        % Menu selected function: MainPlotMenu
        function MainPlotMenuSelected(app, event)
            
        end

        % Menu selected function: OverTimeMenu
        function OverTimeMenuSelected(app, event)
           
        end

        % Menu selected function: OverChannelMenu
        function OverChannelMenuSelected(app, event)
           
        end

        % Menu selected function: matMenu_2
        function matMenu_2Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Time(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continuous ",app.Mainapp.Data.Info.Sorter," Spike Rate Unit over Time ",app.ClustertoshowDropDown.Value),0);
        end

        % Menu selected function: txtMenu_3
        function txtMenu_3Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Time(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continuous ",app.Mainapp.Data.Info.Sorter," Spike Rate Unit over Time ",app.ClustertoshowDropDown.Value),0);
        end

        % Menu selected function: matMenu_3
        function matMenu_3Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Time(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continuous ",app.Mainapp.Data.Info.Sorter," Spike Times ",app.TypeofAnalysisDropDown.Value),0);
        end

        % Menu selected function: txtMenu_4
        function txtMenu_4Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Time(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continuous ",app.Mainapp.Data.Info.Sorter," Spike Times ",app.TypeofAnalysisDropDown.Value),0);
        end

        % Menu selected function: matMenu_4
        function matMenu_4Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Time(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continuous ",app.Mainapp.Data.Info.Sorter," Unit Spike Times ",app.TypeofAnalysisDropDown.Value),0);
        end

        % Menu selected function: txtMenu_5
        function txtMenu_5Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Time(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continuous ",app.Mainapp.Data.Info.Sorter," Unit Spike Times ",app.TypeofAnalysisDropDown.Value),0);
        end

        % Menu selected function: matMenu_5
        function matMenu_5Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Time(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continuous ",app.Mainapp.Data.Info.Sorter," All Spikes Spike Rate over Time ",app.TypeofAnalysisDropDown.Value),0);
        end

        % Menu selected function: txtMenu_6
        function txtMenu_6Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Time(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continuous ",app.Mainapp.Data.Info.Sorter," All Spikes Spike Rate over Time ",app.TypeofAnalysisDropDown.Value),0);
        end

        % Menu selected function: matMenu_6
        function matMenu_6Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Time(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continuous ",app.Mainapp.Data.Info.Sorter," All Spikes Spike Rate over Channel ",app.TypeofAnalysisDropDown.Value),0);
        end

        % Menu selected function: txtMenu_7
        function txtMenu_7Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Time(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continuous ",app.Mainapp.Data.Info.Sorter," All Spikes Spike Rate over Channel ",app.TypeofAnalysisDropDown.Value),0);
        end

        % Menu selected function: Show3DimensionalPlotsMenu
        function Show3DimensionalPlotsMenuSelected(app, event)
            app.TwoORThreeD = "ThreeD";

            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";
            set(app.UIAxes, 'YDir','reverse');
            set(app.UIAxes,'xticklabel',{[]});

            app.UIAxes.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotBackgroundColor;
            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotFontSize;
            
            app.UIAxes_3.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRTimePlotBackgroundColor;
            app.UIAxes_3.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRTimePlotFontSize;

            app.UIAxes_5.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRChannelPlotBackgroundColor;
            app.UIAxes_5.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRChannelPlotFontSize;

            if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel") && isempty(app.AverageWaveforms)
                % For Kilosort we dont have channel information to extract from raw or
                % preprocessed data --> Therefor we take channel closest to position
                [app.AverageWaveforms,~] = Spikes_Module_Get_Waveforms(app.Mainapp.Data,app.Mainapp.Data.Spikes.SpikeTimes,app.Mainapp.Data.Spikes.SpikeChannel,"AverageWaveforms");
            end
            
            if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel")
                [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],app.Mainapp.Data.Info.SpikeType,app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.AverageWaveforms,app.Mainapp.ActiveChannel,app.TypeofAnalysisDropDown.Value,app.Mainapp.PreservePlotChannelLocations);
            else
                [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],app.Mainapp.Data.Info.SpikeType,app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.Mainapp.Data.Spikes.Waveforms,app.Mainapp.ActiveChannel,app.TypeofAnalysisDropDown.Value,app.Mainapp.PreservePlotChannelLocations);
            end
            
            [TempData,app.Mainapp.CurrentPlotData] = Continous_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,PlotInfo,SpikePositions,SpikeAmps,SpikeTimes,Waveforms,WaveformChannel,CluterPositions,app.UIAxes,app.TypeofAnalysisDropDown.Value,app.TextArea,app.EventstoshowDropDown.Value,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,0,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations);
           
            if strcmp(app.TypeofAnalysisDropDown.Value,"Spike Triggered LFP")
                if ~isempty(TempData)
                    app.Mainapp.Data = TempData;
                    PreviousChannelDeletetion = 0;
                    % If Channel were deleted in previous preprocessing flag this
                    % to reset main window parameter after + only allow for prepro
                    % view if prepro was generated
                    if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                        PreviousChannelDeletetion = 1;
                    end
                    %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                    Utility_Show_Info_Loaded_Data(app.Mainapp);
                    %% Wrap up preprocessing
                    [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                    if strcmp(app.Mainapp.DropDown.Value,'Preprocessed Data')
                        if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                            TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                            % Calculate the absolute differences
                            differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                            
                            % Find the index of the minimum difference
                            [~, app.Mainapp.CurrentTimePoints] = min(differences);
                        end
                    end
                    %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                    % Plot functions are fully autonomous without needed the app
                    % object. It is only needed to get the necessary parameter.
                    % Both is combined in one function for convenience.
                    %input 2: 1 if plot time, 0 if no time plot necessary
                    %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                    %input 4: Whether Data plot should run in a movie or not
                    Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
                end
             else
                app.Mainapp.Data = TempData;
             end
        end

        % Menu selected function: FontSizeMenu
        function FontSizeMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'fontsize',app.Mainapp,"EventSpikeWindowDataFontSize");
            waitfor(h);

            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotFontSize;

            if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel") && isempty(app.AverageWaveforms)
                % For Kilosort we dont have channel information to extract from raw or
                % preprocessed data --> Therefor we take channel closest to position
                [app.AverageWaveforms,~] = Spikes_Module_Get_Waveforms(app.Mainapp.Data,app.Mainapp.Data.Spikes.SpikeTimes,app.Mainapp.Data.Spikes.SpikeChannel,"AverageWaveforms");
            end
            
            if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel")
                [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],app.Mainapp.Data.Info.SpikeType,app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.AverageWaveforms,app.Mainapp.ActiveChannel,app.TypeofAnalysisDropDown.Value,app.Mainapp.PreservePlotChannelLocations);
            else
                [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],app.Mainapp.Data.Info.SpikeType,app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.Mainapp.Data.Spikes.Waveforms,app.Mainapp.ActiveChannel,app.TypeofAnalysisDropDown.Value,app.Mainapp.PreservePlotChannelLocations);
            end
            
            [TempData,app.Mainapp.CurrentPlotData] = Continous_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,PlotInfo,SpikePositions,SpikeAmps,SpikeTimes,Waveforms,WaveformChannel,CluterPositions,app.UIAxes,app.TypeofAnalysisDropDown.Value,app.TextArea,app.EventstoshowDropDown.Value,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,0,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations);
           
            if strcmp(app.TypeofAnalysisDropDown.Value,"Spike Triggered LFP")
                if ~isempty(TempData)
                    app.Mainapp.Data = TempData;
                    PreviousChannelDeletetion = 0;
                    % If Channel were deleted in previous preprocessing flag this
                    % to reset main window parameter after + only allow for prepro
                    % view if prepro was generated
                    if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                        PreviousChannelDeletetion = 1;
                    end
                    %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                    Utility_Show_Info_Loaded_Data(app.Mainapp);
                    %% Wrap up preprocessing
                    [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                    if strcmp(app.Mainapp.DropDown.Value,'Preprocessed Data')
                        if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                            TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                            % Calculate the absolute differences
                            differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                            
                            % Find the index of the minimum difference
                            [~, app.Mainapp.CurrentTimePoints] = min(differences);
                        end
                    end
                    %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                    % Plot functions are fully autonomous without needed the app
                    % object. It is only needed to get the necessary parameter.
                    % Both is combined in one function for convenience.
                    %input 2: 1 if plot time, 0 if no time plot necessary
                    %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                    %input 4: Whether Data plot should run in a movie or not
                    Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
                end
             else
                app.Mainapp.Data = TempData;
             end
        end

        % Menu selected function: LineWidthSpikesMenu
        function LineWidthSpikesMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'linewidth',app.Mainapp,"EventSpikeWindowSpikeLineWidth");
            waitfor(h);

            if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel") && isempty(app.AverageWaveforms)
                % For Kilosort we dont have channel information to extract from raw or
                % preprocessed data --> Therefor we take channel closest to position
                [app.AverageWaveforms,~] = Spikes_Module_Get_Waveforms(app.Mainapp.Data,app.Mainapp.Data.Spikes.SpikeTimes,app.Mainapp.Data.Spikes.SpikeChannel,"AverageWaveforms");
            end
            
            if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel")
                [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],app.Mainapp.Data.Info.SpikeType,app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.AverageWaveforms,app.Mainapp.ActiveChannel,app.TypeofAnalysisDropDown.Value,app.Mainapp.PreservePlotChannelLocations);
            else
                [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],app.Mainapp.Data.Info.SpikeType,app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.Mainapp.Data.Spikes.Waveforms,app.Mainapp.ActiveChannel,app.TypeofAnalysisDropDown.Value,app.Mainapp.PreservePlotChannelLocations);
            end
            
            [TempData,app.Mainapp.CurrentPlotData] = Continous_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,PlotInfo,SpikePositions,SpikeAmps,SpikeTimes,Waveforms,WaveformChannel,CluterPositions,app.UIAxes,app.TypeofAnalysisDropDown.Value,app.TextArea,app.EventstoshowDropDown.Value,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,0,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations);
           
            if strcmp(app.TypeofAnalysisDropDown.Value,"Spike Triggered LFP")
                if ~isempty(TempData)
                    app.Mainapp.Data = TempData;
                    PreviousChannelDeletetion = 0;
                    % If Channel were deleted in previous preprocessing flag this
                    % to reset main window parameter after + only allow for prepro
                    % view if prepro was generated
                    if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                        PreviousChannelDeletetion = 1;
                    end
                    %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                    Utility_Show_Info_Loaded_Data(app.Mainapp);
                    %% Wrap up preprocessing
                    [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                    if strcmp(app.Mainapp.DropDown.Value,'Preprocessed Data')
                        if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                            TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                            % Calculate the absolute differences
                            differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                            
                            % Find the index of the minimum difference
                            [~, app.Mainapp.CurrentTimePoints] = min(differences);
                        end
                    end
                    %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                    % Plot functions are fully autonomous without needed the app
                    % object. It is only needed to get the necessary parameter.
                    % Both is combined in one function for convenience.
                    %input 2: 1 if plot time, 0 if no time plot necessary
                    %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                    %input 4: Whether Data plot should run in a movie or not
                    Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
                end
             else
                app.Mainapp.Data = TempData;
             end
        end

        % Menu selected function: LineWidthTriggerMenu
        function LineWidthTriggerMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'linewidth',app.Mainapp,"EventSpikeWindowTriggerLineWidth");
            waitfor(h);

            if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel") && isempty(app.AverageWaveforms)
                % For Kilosort we dont have channel information to extract from raw or
                % preprocessed data --> Therefor we take channel closest to position
                [app.AverageWaveforms,~] = Spikes_Module_Get_Waveforms(app.Mainapp.Data,app.Mainapp.Data.Spikes.SpikeTimes,app.Mainapp.Data.Spikes.SpikeChannel,"AverageWaveforms");
            end
            
            if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel")
                [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],app.Mainapp.Data.Info.SpikeType,app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.AverageWaveforms,app.Mainapp.ActiveChannel,app.TypeofAnalysisDropDown.Value,app.Mainapp.PreservePlotChannelLocations);
            else
                [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],app.Mainapp.Data.Info.SpikeType,app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.Mainapp.Data.Spikes.Waveforms,app.Mainapp.ActiveChannel,app.TypeofAnalysisDropDown.Value,app.Mainapp.PreservePlotChannelLocations);
            end
            
            [TempData,app.Mainapp.CurrentPlotData] = Continous_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,PlotInfo,SpikePositions,SpikeAmps,SpikeTimes,Waveforms,WaveformChannel,CluterPositions,app.UIAxes,app.TypeofAnalysisDropDown.Value,app.TextArea,app.EventstoshowDropDown.Value,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,0,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations);
           
            if strcmp(app.TypeofAnalysisDropDown.Value,"Spike Triggered LFP")
                if ~isempty(TempData)
                    app.Mainapp.Data = TempData;
                    PreviousChannelDeletetion = 0;
                    % If Channel were deleted in previous preprocessing flag this
                    % to reset main window parameter after + only allow for prepro
                    % view if prepro was generated
                    if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                        PreviousChannelDeletetion = 1;
                    end
                    %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                    Utility_Show_Info_Loaded_Data(app.Mainapp);
                    %% Wrap up preprocessing
                    [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                    if strcmp(app.Mainapp.DropDown.Value,'Preprocessed Data')
                        if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                            TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                            % Calculate the absolute differences
                            differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                            
                            % Find the index of the minimum difference
                            [~, app.Mainapp.CurrentTimePoints] = min(differences);
                        end
                    end
                    %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                    % Plot functions are fully autonomous without needed the app
                    % object. It is only needed to get the necessary parameter.
                    % Both is combined in one function for convenience.
                    %input 2: 1 if plot time, 0 if no time plot necessary
                    %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                    %input 4: Whether Data plot should run in a movie or not
                    Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
                end
             else
                app.Mainapp.Data = TempData;
             end
        end

        % Menu selected function: ColorSpikesMenu
        function ColorSpikesMenuSelected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotSpikeColor = selectedColor;
            end

            if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel") && isempty(app.AverageWaveforms)
                % For Kilosort we dont have channel information to extract from raw or
                % preprocessed data --> Therefor we take channel closest to position
                [app.AverageWaveforms,~] = Spikes_Module_Get_Waveforms(app.Mainapp.Data,app.Mainapp.Data.Spikes.SpikeTimes,app.Mainapp.Data.Spikes.SpikeChannel,"AverageWaveforms");
            end
            
            if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel")
                [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],app.Mainapp.Data.Info.SpikeType,app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.AverageWaveforms,app.Mainapp.ActiveChannel,app.TypeofAnalysisDropDown.Value,app.Mainapp.PreservePlotChannelLocations);
            else
                [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],app.Mainapp.Data.Info.SpikeType,app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.Mainapp.Data.Spikes.Waveforms,app.Mainapp.ActiveChannel,app.TypeofAnalysisDropDown.Value,app.Mainapp.PreservePlotChannelLocations);
            end

            if ~strcmp(app.TypeofAnalysisDropDown.Value,"Spike Map") 
                [~,~] = Continous_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,PlotInfo,SpikePositions,SpikeAmps,SpikeTimes,Waveforms,WaveformChannel,CluterPositions,app.UIAxes,"SpikeRateBinSizeChange",app.TextArea,app.EventstoshowDropDown.Value,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,0,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations);
            end
            
            [TempData,app.Mainapp.CurrentPlotData] = Continous_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,PlotInfo,SpikePositions,SpikeAmps,SpikeTimes,Waveforms,WaveformChannel,CluterPositions,app.UIAxes,app.TypeofAnalysisDropDown.Value,app.TextArea,app.EventstoshowDropDown.Value,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,0,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations);
           
            if strcmp(app.TypeofAnalysisDropDown.Value,"Spike Triggered LFP")
                if ~isempty(TempData)
                    app.Mainapp.Data = TempData;
                    PreviousChannelDeletetion = 0;
                    % If Channel were deleted in previous preprocessing flag this
                    % to reset main window parameter after + only allow for prepro
                    % view if prepro was generated
                    if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                        PreviousChannelDeletetion = 1;
                    end
                    %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                    Utility_Show_Info_Loaded_Data(app.Mainapp);
                    %% Wrap up preprocessing
                    [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                    if strcmp(app.Mainapp.DropDown.Value,'Preprocessed Data')
                        if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                            TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                            % Calculate the absolute differences
                            differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                            
                            % Find the index of the minimum difference
                            [~, app.Mainapp.CurrentTimePoints] = min(differences);
                        end
                    end
                    %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                    % Plot functions are fully autonomous without needed the app
                    % object. It is only needed to get the necessary parameter.
                    % Both is combined in one function for convenience.
                    %input 2: 1 if plot time, 0 if no time plot necessary
                    %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                    %input 4: Whether Data plot should run in a movie or not
                    Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
                end
             else
                app.Mainapp.Data = TempData;
             end
        end

        % Menu selected function: ColorTriggerMenu
        function ColorTriggerMenuSelected(app, event)
             % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotTriggerColor = selectedColor;
            end

            if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel") && isempty(app.AverageWaveforms)
                % For Kilosort we dont have channel information to extract from raw or
                % preprocessed data --> Therefor we take channel closest to position
                [app.AverageWaveforms,~] = Spikes_Module_Get_Waveforms(app.Mainapp.Data,app.Mainapp.Data.Spikes.SpikeTimes,app.Mainapp.Data.Spikes.SpikeChannel,"AverageWaveforms");
            end
            
            if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel")
                [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],app.Mainapp.Data.Info.SpikeType,app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.AverageWaveforms,app.Mainapp.ActiveChannel,app.TypeofAnalysisDropDown.Value,app.Mainapp.PreservePlotChannelLocations);
            else
                [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],app.Mainapp.Data.Info.SpikeType,app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.Mainapp.Data.Spikes.Waveforms,app.Mainapp.ActiveChannel,app.TypeofAnalysisDropDown.Value,app.Mainapp.PreservePlotChannelLocations);
            end
            
            [TempData,app.Mainapp.CurrentPlotData] = Continous_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,PlotInfo,SpikePositions,SpikeAmps,SpikeTimes,Waveforms,WaveformChannel,CluterPositions,app.UIAxes,app.TypeofAnalysisDropDown.Value,app.TextArea,app.EventstoshowDropDown.Value,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,0,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations);
           
            if strcmp(app.TypeofAnalysisDropDown.Value,"Spike Triggered LFP")
                if ~isempty(TempData)
                    app.Mainapp.Data = TempData;
                    PreviousChannelDeletetion = 0;
                    % If Channel were deleted in previous preprocessing flag this
                    % to reset main window parameter after + only allow for prepro
                    % view if prepro was generated
                    if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                        PreviousChannelDeletetion = 1;
                    end
                    %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                    Utility_Show_Info_Loaded_Data(app.Mainapp);
                    %% Wrap up preprocessing
                    [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                    if strcmp(app.Mainapp.DropDown.Value,'Preprocessed Data')
                        if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                            TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                            % Calculate the absolute differences
                            differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                            
                            % Find the index of the minimum difference
                            [~, app.Mainapp.CurrentTimePoints] = min(differences);
                        end
                    end
                    %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                    % Plot functions are fully autonomous without needed the app
                    % object. It is only needed to get the necessary parameter.
                    % Both is combined in one function for convenience.
                    %input 2: 1 if plot time, 0 if no time plot necessary
                    %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                    %input 4: Whether Data plot should run in a movie or not
                    Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
                end
             else
                app.Mainapp.Data = TempData;
             end
        end

        % Menu selected function: BackgorundColorMenu
        function BackgorundColorMenuSelected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotBackgroundColor = selectedColor;
                app.UIAxes.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotBackgroundColor;

            end
        end

        % Menu selected function: XLabelMenu
        function XLabelMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'xlabel',app.Mainapp,"EventSpikeWindowSpikeXLabel");
            waitfor(h);

            if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel") && isempty(app.AverageWaveforms)
                % For Kilosort we dont have channel information to extract from raw or
                % preprocessed data --> Therefor we take channel closest to position
                [app.AverageWaveforms,~] = Spikes_Module_Get_Waveforms(app.Mainapp.Data,app.Mainapp.Data.Spikes.SpikeTimes,app.Mainapp.Data.Spikes.SpikeChannel,"AverageWaveforms");
            end
            
            if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel")
                [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],app.Mainapp.Data.Info.SpikeType,app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.AverageWaveforms,app.Mainapp.ActiveChannel,app.TypeofAnalysisDropDown.Value,app.Mainapp.PreservePlotChannelLocations);
            else
                [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],app.Mainapp.Data.Info.SpikeType,app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.Mainapp.Data.Spikes.Waveforms,app.Mainapp.ActiveChannel,app.TypeofAnalysisDropDown.Value,app.Mainapp.PreservePlotChannelLocations);
            end
            
            [TempData,app.Mainapp.CurrentPlotData] = Continous_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,PlotInfo,SpikePositions,SpikeAmps,SpikeTimes,Waveforms,WaveformChannel,CluterPositions,app.UIAxes,app.TypeofAnalysisDropDown.Value,app.TextArea,app.EventstoshowDropDown.Value,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,0,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations);
           
            if strcmp(app.TypeofAnalysisDropDown.Value,"Spike Triggered LFP")
                if ~isempty(TempData)
                    app.Mainapp.Data = TempData;
                    PreviousChannelDeletetion = 0;
                    % If Channel were deleted in previous preprocessing flag this
                    % to reset main window parameter after + only allow for prepro
                    % view if prepro was generated
                    if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                        PreviousChannelDeletetion = 1;
                    end
                    %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                    Utility_Show_Info_Loaded_Data(app.Mainapp);
                    %% Wrap up preprocessing
                    [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                    if strcmp(app.Mainapp.DropDown.Value,'Preprocessed Data')
                        if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                            TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                            % Calculate the absolute differences
                            differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                            
                            % Find the index of the minimum difference
                            [~, app.Mainapp.CurrentTimePoints] = min(differences);
                        end
                    end
                    %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                    % Plot functions are fully autonomous without needed the app
                    % object. It is only needed to get the necessary parameter.
                    % Both is combined in one function for convenience.
                    %input 2: 1 if plot time, 0 if no time plot necessary
                    %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                    %input 4: Whether Data plot should run in a movie or not
                    Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
                end
             else
                app.Mainapp.Data = TempData;
             end
        end

        % Menu selected function: YLabelMenu
        function YLabelMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'ylabel',app.Mainapp,"EventSpikeWindowSpikeYLabel");
            waitfor(h);

            if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel") && isempty(app.AverageWaveforms)
                % For Kilosort we dont have channel information to extract from raw or
                % preprocessed data --> Therefor we take channel closest to position
                [app.AverageWaveforms,~] = Spikes_Module_Get_Waveforms(app.Mainapp.Data,app.Mainapp.Data.Spikes.SpikeTimes,app.Mainapp.Data.Spikes.SpikeChannel,"AverageWaveforms");
            end
            
            if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel")
                [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],app.Mainapp.Data.Info.SpikeType,app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.AverageWaveforms,app.Mainapp.ActiveChannel,app.TypeofAnalysisDropDown.Value,app.Mainapp.PreservePlotChannelLocations);
            else
                [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],app.Mainapp.Data.Info.SpikeType,app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.Mainapp.Data.Spikes.Waveforms,app.Mainapp.ActiveChannel,app.TypeofAnalysisDropDown.Value,app.Mainapp.PreservePlotChannelLocations);
            end
            
            [TempData,app.Mainapp.CurrentPlotData] = Continous_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,PlotInfo,SpikePositions,SpikeAmps,SpikeTimes,Waveforms,WaveformChannel,CluterPositions,app.UIAxes,app.TypeofAnalysisDropDown.Value,app.TextArea,app.EventstoshowDropDown.Value,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,0,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations);
           
            if strcmp(app.TypeofAnalysisDropDown.Value,"Spike Triggered LFP")
                if ~isempty(TempData)
                    app.Mainapp.Data = TempData;
                    PreviousChannelDeletetion = 0;
                    % If Channel were deleted in previous preprocessing flag this
                    % to reset main window parameter after + only allow for prepro
                    % view if prepro was generated
                    if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                        PreviousChannelDeletetion = 1;
                    end
                    %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                    Utility_Show_Info_Loaded_Data(app.Mainapp);
                    %% Wrap up preprocessing
                    [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                    if strcmp(app.Mainapp.DropDown.Value,'Preprocessed Data')
                        if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                            TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                            % Calculate the absolute differences
                            differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                            
                            % Find the index of the minimum difference
                            [~, app.Mainapp.CurrentTimePoints] = min(differences);
                        end
                    end
                    %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                    % Plot functions are fully autonomous without needed the app
                    % object. It is only needed to get the necessary parameter.
                    % Both is combined in one function for convenience.
                    %input 2: 1 if plot time, 0 if no time plot necessary
                    %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                    %input 4: Whether Data plot should run in a movie or not
                    Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
                end
             else
                app.Mainapp.Data = TempData;
             end
        end

        % Menu selected function: RESETMenu
        function RESETMenuSelected(app, event)
            [app.Mainapp.PlotAppearance] = Organize_Set_Standard_PlotAppearance("InternalEventSpikePlot",app.Mainapp.PlotAppearance);

            app.UIAxes.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotBackgroundColor;
            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotFontSize;
            
            app.UIAxes_3.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRTimePlotBackgroundColor;
            app.UIAxes_3.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRTimePlotFontSize;

            app.UIAxes_5.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRChannelPlotBackgroundColor;
            app.UIAxes_5.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRChannelPlotFontSize;

            if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel") && isempty(app.AverageWaveforms)
                % For Kilosort we dont have channel information to extract from raw or
                % preprocessed data --> Therefor we take channel closest to position
                [app.AverageWaveforms,~] = Spikes_Module_Get_Waveforms(app.Mainapp.Data,app.Mainapp.Data.Spikes.SpikeTimes,app.Mainapp.Data.Spikes.SpikeChannel,"AverageWaveforms");
            end
            
            if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel")
                [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],app.Mainapp.Data.Info.SpikeType,app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.AverageWaveforms,app.Mainapp.ActiveChannel,app.TypeofAnalysisDropDown.Value,app.Mainapp.PreservePlotChannelLocations);
            else
                [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],app.Mainapp.Data.Info.SpikeType,app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.Mainapp.Data.Spikes.Waveforms,app.Mainapp.ActiveChannel,app.TypeofAnalysisDropDown.Value,app.Mainapp.PreservePlotChannelLocations);
            end
            
            [TempData,app.Mainapp.CurrentPlotData] = Continous_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,PlotInfo,SpikePositions,SpikeAmps,SpikeTimes,Waveforms,WaveformChannel,CluterPositions,app.UIAxes,app.TypeofAnalysisDropDown.Value,app.TextArea,app.EventstoshowDropDown.Value,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,0,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations);
           
            if ~strcmp(app.TypeofAnalysisDropDown.Value,"Spike Map") 
                [~,~] = Continous_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,PlotInfo,SpikePositions,SpikeAmps,SpikeTimes,Waveforms,WaveformChannel,CluterPositions,app.UIAxes,"SpikeRateBinSizeChange",app.TextArea,app.EventstoshowDropDown.Value,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,0,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations);
            end

            if strcmp(app.TypeofAnalysisDropDown.Value,"Spike Triggered LFP")
                if ~isempty(TempData)
                    app.Mainapp.Data = TempData;
                    PreviousChannelDeletetion = 0;
                    % If Channel were deleted in previous preprocessing flag this
                    % to reset main window parameter after + only allow for prepro
                    % view if prepro was generated
                    if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                        PreviousChannelDeletetion = 1;
                    end
                    %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                    Utility_Show_Info_Loaded_Data(app.Mainapp);
                    %% Wrap up preprocessing
                    [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                    if strcmp(app.Mainapp.DropDown.Value,'Preprocessed Data')
                        if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                            TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                            % Calculate the absolute differences
                            differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                            
                            % Find the index of the minimum difference
                            [~, app.Mainapp.CurrentTimePoints] = min(differences);
                        end
                    end
                    %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                    % Plot functions are fully autonomous without needed the app
                    % object. It is only needed to get the necessary parameter.
                    % Both is combined in one function for convenience.
                    %input 2: 1 if plot time, 0 if no time plot necessary
                    %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                    %input 4: Whether Data plot should run in a movie or not
                    Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
                end
             else
                app.Mainapp.Data = TempData;
             end
        end

        % Menu selected function: FontSizeMenu_2
        function FontSizeMenu_2Selected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes_3,'fontsize',app.Mainapp,"EventSpikeWindowSpikeRateTimeFontSize");
            waitfor(h);

            app.UIAxes_3.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRTimePlotFontSize;
        end

        % Menu selected function: BarColorMenu
        function BarColorMenuSelected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRTimePlotBarColor = selectedColor;
            end

            if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel") && isempty(app.AverageWaveforms)
                % For Kilosort we dont have channel information to extract from raw or
                % preprocessed data --> Therefor we take channel closest to position
                [app.AverageWaveforms,~] = Spikes_Module_Get_Waveforms(app.Mainapp.Data,app.Mainapp.Data.Spikes.SpikeTimes,app.Mainapp.Data.Spikes.SpikeChannel,"AverageWaveforms");
            end
            
            if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel")
                [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],app.Mainapp.Data.Info.SpikeType,app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.AverageWaveforms,app.Mainapp.ActiveChannel,app.TypeofAnalysisDropDown.Value,app.Mainapp.PreservePlotChannelLocations);
            else
                [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],app.Mainapp.Data.Info.SpikeType,app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.Mainapp.Data.Spikes.Waveforms,app.Mainapp.ActiveChannel,app.TypeofAnalysisDropDown.Value,app.Mainapp.PreservePlotChannelLocations);
            end
            
            [TempData,app.Mainapp.CurrentPlotData] = Continous_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,PlotInfo,SpikePositions,SpikeAmps,SpikeTimes,Waveforms,WaveformChannel,CluterPositions,app.UIAxes,app.TypeofAnalysisDropDown.Value,app.TextArea,app.EventstoshowDropDown.Value,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,0,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations);
           
            if strcmp(app.TypeofAnalysisDropDown.Value,"Spike Triggered LFP")
                if ~isempty(TempData)
                    app.Mainapp.Data = TempData;
                    PreviousChannelDeletetion = 0;
                    % If Channel were deleted in previous preprocessing flag this
                    % to reset main window parameter after + only allow for prepro
                    % view if prepro was generated
                    if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                        PreviousChannelDeletetion = 1;
                    end
                    %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                    Utility_Show_Info_Loaded_Data(app.Mainapp);
                    %% Wrap up preprocessing
                    [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                    if strcmp(app.Mainapp.DropDown.Value,'Preprocessed Data')
                        if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                            TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                            % Calculate the absolute differences
                            differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                            
                            % Find the index of the minimum difference
                            [~, app.Mainapp.CurrentTimePoints] = min(differences);
                        end
                    end
                    %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                    % Plot functions are fully autonomous without needed the app
                    % object. It is only needed to get the necessary parameter.
                    % Both is combined in one function for convenience.
                    %input 2: 1 if plot time, 0 if no time plot necessary
                    %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                    %input 4: Whether Data plot should run in a movie or not
                    Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
                end
             else
                app.Mainapp.Data = TempData;
             end
        end

        % Menu selected function: BackgroundColorMenu
        function BackgroundColorMenuSelected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRTimePlotBackgroundColor = selectedColor;
                app.UIAxes_3.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRTimePlotBackgroundColor;
            end
        end

        % Menu selected function: XLabelMenu_2
        function XLabelMenu_2Selected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes_3,'xlabel',app.Mainapp,"EventSpikeWindowSpikeRateTimeXLabel");
            waitfor(h);

            if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel") && isempty(app.AverageWaveforms)
                % For Kilosort we dont have channel information to extract from raw or
                % preprocessed data --> Therefor we take channel closest to position
                [app.AverageWaveforms,~] = Spikes_Module_Get_Waveforms(app.Mainapp.Data,app.Mainapp.Data.Spikes.SpikeTimes,app.Mainapp.Data.Spikes.SpikeChannel,"AverageWaveforms");
            end
            
            if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel")
                [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],app.Mainapp.Data.Info.SpikeType,app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.AverageWaveforms,app.Mainapp.ActiveChannel,app.TypeofAnalysisDropDown.Value,app.Mainapp.PreservePlotChannelLocations);
            else
                [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],app.Mainapp.Data.Info.SpikeType,app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.Mainapp.Data.Spikes.Waveforms,app.Mainapp.ActiveChannel,app.TypeofAnalysisDropDown.Value,app.Mainapp.PreservePlotChannelLocations);
            end
            
            [TempData,app.Mainapp.CurrentPlotData] = Continous_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,PlotInfo,SpikePositions,SpikeAmps,SpikeTimes,Waveforms,WaveformChannel,CluterPositions,app.UIAxes,app.TypeofAnalysisDropDown.Value,app.TextArea,app.EventstoshowDropDown.Value,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,0,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations);
           
            if strcmp(app.TypeofAnalysisDropDown.Value,"Spike Triggered LFP")
                if ~isempty(TempData)
                    app.Mainapp.Data = TempData;
                    PreviousChannelDeletetion = 0;
                    % If Channel were deleted in previous preprocessing flag this
                    % to reset main window parameter after + only allow for prepro
                    % view if prepro was generated
                    if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                        PreviousChannelDeletetion = 1;
                    end
                    %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                    Utility_Show_Info_Loaded_Data(app.Mainapp);
                    %% Wrap up preprocessing
                    [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                    if strcmp(app.Mainapp.DropDown.Value,'Preprocessed Data')
                        if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                            TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                            % Calculate the absolute differences
                            differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                            
                            % Find the index of the minimum difference
                            [~, app.Mainapp.CurrentTimePoints] = min(differences);
                        end
                    end
                    %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                    % Plot functions are fully autonomous without needed the app
                    % object. It is only needed to get the necessary parameter.
                    % Both is combined in one function for convenience.
                    %input 2: 1 if plot time, 0 if no time plot necessary
                    %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                    %input 4: Whether Data plot should run in a movie or not
                    Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
                end
             else
                app.Mainapp.Data = TempData;
             end
        end

        % Menu selected function: YLabelMenu_2
        function YLabelMenu_2Selected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes_3,'ylabel',app.Mainapp,"EventSpikeWindowSpikeRateTimeYLabel");
            waitfor(h);

            if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel") && isempty(app.AverageWaveforms)
                % For Kilosort we dont have channel information to extract from raw or
                % preprocessed data --> Therefor we take channel closest to position
                [app.AverageWaveforms,~] = Spikes_Module_Get_Waveforms(app.Mainapp.Data,app.Mainapp.Data.Spikes.SpikeTimes,app.Mainapp.Data.Spikes.SpikeChannel,"AverageWaveforms");
            end
            
            if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel")
                [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],app.Mainapp.Data.Info.SpikeType,app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.AverageWaveforms,app.Mainapp.ActiveChannel,app.TypeofAnalysisDropDown.Value,app.Mainapp.PreservePlotChannelLocations);
            else
                [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],app.Mainapp.Data.Info.SpikeType,app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.Mainapp.Data.Spikes.Waveforms,app.Mainapp.ActiveChannel,app.TypeofAnalysisDropDown.Value,app.Mainapp.PreservePlotChannelLocations);
            end
            
            [TempData,app.Mainapp.CurrentPlotData] = Continous_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,PlotInfo,SpikePositions,SpikeAmps,SpikeTimes,Waveforms,WaveformChannel,CluterPositions,app.UIAxes,app.TypeofAnalysisDropDown.Value,app.TextArea,app.EventstoshowDropDown.Value,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,0,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations);
           
            if strcmp(app.TypeofAnalysisDropDown.Value,"Spike Triggered LFP")
                if ~isempty(TempData)
                    app.Mainapp.Data = TempData;
                    PreviousChannelDeletetion = 0;
                    % If Channel were deleted in previous preprocessing flag this
                    % to reset main window parameter after + only allow for prepro
                    % view if prepro was generated
                    if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                        PreviousChannelDeletetion = 1;
                    end
                    %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                    Utility_Show_Info_Loaded_Data(app.Mainapp);
                    %% Wrap up preprocessing
                    [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                    if strcmp(app.Mainapp.DropDown.Value,'Preprocessed Data')
                        if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                            TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                            % Calculate the absolute differences
                            differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                            
                            % Find the index of the minimum difference
                            [~, app.Mainapp.CurrentTimePoints] = min(differences);
                        end
                    end
                    %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                    % Plot functions are fully autonomous without needed the app
                    % object. It is only needed to get the necessary parameter.
                    % Both is combined in one function for convenience.
                    %input 2: 1 if plot time, 0 if no time plot necessary
                    %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                    %input 4: Whether Data plot should run in a movie or not
                    Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
                end
             else
                app.Mainapp.Data = TempData;
             end
        end

        % Menu selected function: RESETMenu_2
        function RESETMenu_2Selected(app, event)
            [app.Mainapp.PlotAppearance] = Organize_Set_Standard_PlotAppearance("InternalEventSpikePlot",app.Mainapp.PlotAppearance);

            app.UIAxes.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotBackgroundColor;
            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotFontSize;
            
            app.UIAxes_3.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRTimePlotBackgroundColor;
            app.UIAxes_3.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRTimePlotFontSize;

            app.UIAxes_5.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRChannelPlotBackgroundColor;
            app.UIAxes_5.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRChannelPlotFontSize;

            if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel") && isempty(app.AverageWaveforms)
                % For Kilosort we dont have channel information to extract from raw or
                % preprocessed data --> Therefor we take channel closest to position
                [app.AverageWaveforms,~] = Spikes_Module_Get_Waveforms(app.Mainapp.Data,app.Mainapp.Data.Spikes.SpikeTimes,app.Mainapp.Data.Spikes.SpikeChannel,"AverageWaveforms");
            end
            
            if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel")
                [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],app.Mainapp.Data.Info.SpikeType,app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.AverageWaveforms,app.Mainapp.ActiveChannel,app.TypeofAnalysisDropDown.Value,app.Mainapp.PreservePlotChannelLocations);
            else
                [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],app.Mainapp.Data.Info.SpikeType,app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.Mainapp.Data.Spikes.Waveforms,app.Mainapp.ActiveChannel,app.TypeofAnalysisDropDown.Value,app.Mainapp.PreservePlotChannelLocations);
            end
            
            [TempData,app.Mainapp.CurrentPlotData] = Continous_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,PlotInfo,SpikePositions,SpikeAmps,SpikeTimes,Waveforms,WaveformChannel,CluterPositions,app.UIAxes,app.TypeofAnalysisDropDown.Value,app.TextArea,app.EventstoshowDropDown.Value,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,0,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations);
           
            if strcmp(app.TypeofAnalysisDropDown.Value,"Spike Triggered LFP")
                if ~isempty(TempData)
                    app.Mainapp.Data = TempData;
                    PreviousChannelDeletetion = 0;
                    % If Channel were deleted in previous preprocessing flag this
                    % to reset main window parameter after + only allow for prepro
                    % view if prepro was generated
                    if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                        PreviousChannelDeletetion = 1;
                    end
                    %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                    Utility_Show_Info_Loaded_Data(app.Mainapp);
                    %% Wrap up preprocessing
                    [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                    if strcmp(app.Mainapp.DropDown.Value,'Preprocessed Data')
                        if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                            TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                            % Calculate the absolute differences
                            differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                            
                            % Find the index of the minimum difference
                            [~, app.Mainapp.CurrentTimePoints] = min(differences);
                        end
                    end
                    %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                    % Plot functions are fully autonomous without needed the app
                    % object. It is only needed to get the necessary parameter.
                    % Both is combined in one function for convenience.
                    %input 2: 1 if plot time, 0 if no time plot necessary
                    %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                    %input 4: Whether Data plot should run in a movie or not
                    Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
                end
             else
                app.Mainapp.Data = TempData;
             end
        end

        % Menu selected function: FontSizeMenu_3
        function FontSizeMenu_3Selected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes_5,'fontsize',app.Mainapp,"EventSpikeWindowSpikeRateChannelFontSize");
            waitfor(h);

            app.UIAxes_5.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRChannelPlotFontSize;
        end

        % Menu selected function: BarColorMenu_2
        function BarColorMenu_2Selected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRChannelPlotBarColor = selectedColor;
            end

            if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel") && isempty(app.AverageWaveforms)
                % For Kilosort we dont have channel information to extract from raw or
                % preprocessed data --> Therefor we take channel closest to position
                [app.AverageWaveforms,~] = Spikes_Module_Get_Waveforms(app.Mainapp.Data,app.Mainapp.Data.Spikes.SpikeTimes,app.Mainapp.Data.Spikes.SpikeChannel,"AverageWaveforms");
            end
            
            if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel")
                [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],app.Mainapp.Data.Info.SpikeType,app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.AverageWaveforms,app.Mainapp.ActiveChannel,app.TypeofAnalysisDropDown.Value,app.Mainapp.PreservePlotChannelLocations);
            else
                [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],app.Mainapp.Data.Info.SpikeType,app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.Mainapp.Data.Spikes.Waveforms,app.Mainapp.ActiveChannel,app.TypeofAnalysisDropDown.Value,app.Mainapp.PreservePlotChannelLocations);
            end
            
            [TempData,app.Mainapp.CurrentPlotData] = Continous_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,PlotInfo,SpikePositions,SpikeAmps,SpikeTimes,Waveforms,WaveformChannel,CluterPositions,app.UIAxes,app.TypeofAnalysisDropDown.Value,app.TextArea,app.EventstoshowDropDown.Value,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,0,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations);
           
            if strcmp(app.TypeofAnalysisDropDown.Value,"Spike Triggered LFP")
                if ~isempty(TempData)
                    app.Mainapp.Data = TempData;
                    PreviousChannelDeletetion = 0;
                    % If Channel were deleted in previous preprocessing flag this
                    % to reset main window parameter after + only allow for prepro
                    % view if prepro was generated
                    if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                        PreviousChannelDeletetion = 1;
                    end
                    %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                    Utility_Show_Info_Loaded_Data(app.Mainapp);
                    %% Wrap up preprocessing
                    [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                    if strcmp(app.Mainapp.DropDown.Value,'Preprocessed Data')
                        if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                            TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                            % Calculate the absolute differences
                            differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                            
                            % Find the index of the minimum difference
                            [~, app.Mainapp.CurrentTimePoints] = min(differences);
                        end
                    end
                    %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                    % Plot functions are fully autonomous without needed the app
                    % object. It is only needed to get the necessary parameter.
                    % Both is combined in one function for convenience.
                    %input 2: 1 if plot time, 0 if no time plot necessary
                    %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                    %input 4: Whether Data plot should run in a movie or not
                    Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
                end
             else
                app.Mainapp.Data = TempData;
             end
        end

        % Menu selected function: BackgroundColorMenu_2
        function BackgroundColorMenu_2Selected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRChannelPlotBackgroundColor = selectedColor;
                app.UIAxes_5.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRChannelPlotBackgroundColor;
            end
        end

        % Menu selected function: XLabelMenu_3
        function XLabelMenu_3Selected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes_5,'xlabel',app.Mainapp,"EventSpikeWindowSpikeRateChannelXLabel");
            waitfor(h);

            if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel") && isempty(app.AverageWaveforms)
                % For Kilosort we dont have channel information to extract from raw or
                % preprocessed data --> Therefor we take channel closest to position
                [app.AverageWaveforms,~] = Spikes_Module_Get_Waveforms(app.Mainapp.Data,app.Mainapp.Data.Spikes.SpikeTimes,app.Mainapp.Data.Spikes.SpikeChannel,"AverageWaveforms");
            end
            
            if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel")
                [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],app.Mainapp.Data.Info.SpikeType,app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.AverageWaveforms,app.Mainapp.ActiveChannel,app.TypeofAnalysisDropDown.Value,app.Mainapp.PreservePlotChannelLocations);
            else
                [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],app.Mainapp.Data.Info.SpikeType,app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.Mainapp.Data.Spikes.Waveforms,app.Mainapp.ActiveChannel,app.TypeofAnalysisDropDown.Value,app.Mainapp.PreservePlotChannelLocations);
            end
            
            [TempData,app.Mainapp.CurrentPlotData] = Continous_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,PlotInfo,SpikePositions,SpikeAmps,SpikeTimes,Waveforms,WaveformChannel,CluterPositions,app.UIAxes,app.TypeofAnalysisDropDown.Value,app.TextArea,app.EventstoshowDropDown.Value,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,0,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations);
           
            if strcmp(app.TypeofAnalysisDropDown.Value,"Spike Triggered LFP")
                if ~isempty(TempData)
                    app.Mainapp.Data = TempData;
                    PreviousChannelDeletetion = 0;
                    % If Channel were deleted in previous preprocessing flag this
                    % to reset main window parameter after + only allow for prepro
                    % view if prepro was generated
                    if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                        PreviousChannelDeletetion = 1;
                    end
                    %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                    Utility_Show_Info_Loaded_Data(app.Mainapp);
                    %% Wrap up preprocessing
                    [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                    if strcmp(app.Mainapp.DropDown.Value,'Preprocessed Data')
                        if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                            TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                            % Calculate the absolute differences
                            differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                            
                            % Find the index of the minimum difference
                            [~, app.Mainapp.CurrentTimePoints] = min(differences);
                        end
                    end
                    %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                    % Plot functions are fully autonomous without needed the app
                    % object. It is only needed to get the necessary parameter.
                    % Both is combined in one function for convenience.
                    %input 2: 1 if plot time, 0 if no time plot necessary
                    %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                    %input 4: Whether Data plot should run in a movie or not
                    Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
                end
             else
                app.Mainapp.Data = TempData;
             end
        end

        % Menu selected function: YLabelMenu_3
        function YLabelMenu_3Selected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes_5,'ylabel',app.Mainapp,"EventSpikeWindowSpikeRateChannelYLabel");
            waitfor(h);

            if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel") && isempty(app.AverageWaveforms)
                % For Kilosort we dont have channel information to extract from raw or
                % preprocessed data --> Therefor we take channel closest to position
                [app.AverageWaveforms,~] = Spikes_Module_Get_Waveforms(app.Mainapp.Data,app.Mainapp.Data.Spikes.SpikeTimes,app.Mainapp.Data.Spikes.SpikeChannel,"AverageWaveforms");
            end
            
            if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel")
                [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],app.Mainapp.Data.Info.SpikeType,app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.AverageWaveforms,app.Mainapp.ActiveChannel,app.TypeofAnalysisDropDown.Value,app.Mainapp.PreservePlotChannelLocations);
            else
                [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],app.Mainapp.Data.Info.SpikeType,app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.Mainapp.Data.Spikes.Waveforms,app.Mainapp.ActiveChannel,app.TypeofAnalysisDropDown.Value,app.Mainapp.PreservePlotChannelLocations);
            end
            
            [TempData,app.Mainapp.CurrentPlotData] = Continous_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,PlotInfo,SpikePositions,SpikeAmps,SpikeTimes,Waveforms,WaveformChannel,CluterPositions,app.UIAxes,app.TypeofAnalysisDropDown.Value,app.TextArea,app.EventstoshowDropDown.Value,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,0,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations);
           
            if strcmp(app.TypeofAnalysisDropDown.Value,"Spike Triggered LFP")
                if ~isempty(TempData)
                    app.Mainapp.Data = TempData;
                    PreviousChannelDeletetion = 0;
                    % If Channel were deleted in previous preprocessing flag this
                    % to reset main window parameter after + only allow for prepro
                    % view if prepro was generated
                    if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                        PreviousChannelDeletetion = 1;
                    end
                    %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                    Utility_Show_Info_Loaded_Data(app.Mainapp);
                    %% Wrap up preprocessing
                    [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                    if strcmp(app.Mainapp.DropDown.Value,'Preprocessed Data')
                        if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                            TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                            % Calculate the absolute differences
                            differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                            
                            % Find the index of the minimum difference
                            [~, app.Mainapp.CurrentTimePoints] = min(differences);
                        end
                    end
                    %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                    % Plot functions are fully autonomous without needed the app
                    % object. It is only needed to get the necessary parameter.
                    % Both is combined in one function for convenience.
                    %input 2: 1 if plot time, 0 if no time plot necessary
                    %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                    %input 4: Whether Data plot should run in a movie or not
                    Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
                end
             else
                app.Mainapp.Data = TempData;
             end
        end

        % Menu selected function: RESETMenu_3
        function RESETMenu_3Selected(app, event)
            [app.Mainapp.PlotAppearance] = Organize_Set_Standard_PlotAppearance("InternalEventSpikePlot",app.Mainapp.PlotAppearance);

            app.UIAxes.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotBackgroundColor;
            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotFontSize;
            
            app.UIAxes_3.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRTimePlotBackgroundColor;
            app.UIAxes_3.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRTimePlotFontSize;

            app.UIAxes_5.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRChannelPlotBackgroundColor;
            app.UIAxes_5.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRChannelPlotFontSize;

            if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel") && isempty(app.AverageWaveforms)
                % For Kilosort we dont have channel information to extract from raw or
                % preprocessed data --> Therefor we take channel closest to position
                [app.AverageWaveforms,~] = Spikes_Module_Get_Waveforms(app.Mainapp.Data,app.Mainapp.Data.Spikes.SpikeTimes,app.Mainapp.Data.Spikes.SpikeChannel,"AverageWaveforms");
            end
            
            if strcmp(app.TypeofAnalysisDropDown.Value,"Average Waveforms Across Channel")
                [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],app.Mainapp.Data.Info.SpikeType,app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.AverageWaveforms,app.Mainapp.ActiveChannel,app.TypeofAnalysisDropDown.Value,app.Mainapp.PreservePlotChannelLocations);
            else
                [SpikeTimes,SpikePositions,SpikeAmps,CluterPositions,Waveforms,~,PlotInfo,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,WaveformChannel] = Continous_Spikes_Prepare_Plots(app.Mainapp.Data,app.ChannelSelectionforPlottingEditField,app.WaveformSelectionforPlottingEditField,app.ClustertoshowDropDown,[],app.Mainapp.Data.Info.SpikeType,app.TypeofAnalysisDropDown,app.SpikeRateNumBinsEditField,app.TimeWindowSpiketriggredLFPEditField,1,app.EventstoshowDropDown.Value,app.Mainapp.Data.Spikes.Waveforms,app.Mainapp.ActiveChannel,app.TypeofAnalysisDropDown.Value,app.Mainapp.PreservePlotChannelLocations);
            end
            
            [TempData,app.Mainapp.CurrentPlotData] = Continous_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,PlotInfo,SpikePositions,SpikeAmps,SpikeTimes,Waveforms,WaveformChannel,CluterPositions,app.UIAxes,app.TypeofAnalysisDropDown.Value,app.TextArea,app.EventstoshowDropDown.Value,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,0,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations);
           
            if strcmp(app.TypeofAnalysisDropDown.Value,"Spike Triggered LFP")
                if ~isempty(TempData)
                    app.Mainapp.Data = TempData;
                    PreviousChannelDeletetion = 0;
                    % If Channel were deleted in previous preprocessing flag this
                    % to reset main window parameter after + only allow for prepro
                    % view if prepro was generated
                    if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                        PreviousChannelDeletetion = 1;
                    end
                    %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                    Utility_Show_Info_Loaded_Data(app.Mainapp);
                    %% Wrap up preprocessing
                    [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                    if strcmp(app.Mainapp.DropDown.Value,'Preprocessed Data')
                        if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                            TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                            % Calculate the absolute differences
                            differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                            
                            % Find the index of the minimum difference
                            [~, app.Mainapp.CurrentTimePoints] = min(differences);
                        end
                    end
                    %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                    % Plot functions are fully autonomous without needed the app
                    % object. It is only needed to get the necessary parameter.
                    % Both is combined in one function for convenience.
                    %input 2: 1 if plot time, 0 if no time plot necessary
                    %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                    %input 4: Whether Data plot should run in a movie or not
                    Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
                end
             else
                app.Mainapp.Data = TempData;
             end
        end

        % Menu selected function: SaveCustomeAppearanceMenu
        function SaveCustomeAppearanceMenuSelected(app, event)
            app.Mainapp.PlotAppearance = Utility_Save_Load_Delete_Plot_Appearance(app.Mainapp.PlotAppearance,app.Mainapp.executableFolder,"Save");
        end

        % Menu selected function: DeleteCustomeAppearanceMenu
        function DeleteCustomeAppearanceMenuSelected(app, event)
            app.Mainapp.PlotAppearance = Utility_Save_Load_Delete_Plot_Appearance(app.Mainapp.PlotAppearance,app.Mainapp.executableFolder,"Delete");
        end

        % Close request function: ContinuousSpikeAnalysisUIFigure
        function ContinuousSpikeAnalysisUIFigureCloseRequest(app, event)
            app.Mainapp.ConKilosortSpikesWindow = [];

            if ~isempty(app.Mainapp.ProbeViewWindowHandle) % delete option from probe view 
                for i = 1:length(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                    if strcmp(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Cont. Spike Analysis')
                        app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items(i) = [];
                        break;
                    end
                end
            else % if no probe view available
 
            end

            delete(app);
            
        end

        % Menu selected function: SVGMenu
        function SVGMenuSelected(app, event)
            [file, path] = uiputfile('*.svg', 'Save as');
            
            exportgraphics(app.ContinuousSpikeAnalysisUIFigure, fullfile(path,file), 'ContentType', 'vector');
        end

        % Menu selected function: xlsxMenu
        function xlsxMenuSelected(app, event)
            TimeRangePlot = app.Mainapp.Data.Time(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".xlsx",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continuous ",app.Mainapp.Data.Info.Sorter," All Spikes Spike Rate over Time ",app.TypeofAnalysisDropDown.Value),0);
        end

        % Menu selected function: xlsxMenu_3
        function xlsxMenu_3Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Time(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".xlsx",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continuous ",app.Mainapp.Data.Info.Sorter," Unit Spike Times ",app.TypeofAnalysisDropDown.Value),0);
        end

        % Menu selected function: xlsxMenu_2
        function xlsxMenu_2Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Time(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".xlsx",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continuous ",app.Mainapp.Data.Info.Sorter," Spike Times ",app.TypeofAnalysisDropDown.Value),0);
        end

        % Menu selected function: xlsxMenu_4
        function xlsxMenu_4Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Time(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".xlsx",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continuous ",app.Mainapp.Data.Info.Sorter," All Spikes Spike Rate over Channel ",app.TypeofAnalysisDropDown.Value),0);
        end

        % Menu selected function: xlsxMenu_5
        function xlsxMenu_5Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Time(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".xlsx",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continuous ",app.Mainapp.Data.Info.Sorter," Spike Rate Unit over Time ",app.ClustertoshowDropDown.Value),0);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            Utility_Open_UIAxes_as_Figure(app.Mainapp.Data,app.UIAxes,strcat("ConSpikes ",app.TypeofAnalysisDropDown.Value));
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            Utility_Open_UIAxes_as_Figure(app.Mainapp.Data,app.UIAxes_3,"SpikeRateTime");
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            Utility_Open_UIAxes_as_Figure(app.Mainapp.Data,app.UIAxes_5,"SpikeRateChannel");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ContinuousSpikeAnalysisUIFigure = uifigure('Visible', 'off');
            app.ContinuousSpikeAnalysisUIFigure.Name = 'Continuous Spike Analysis';
            app.ContinuousSpikeAnalysisUIFigure.Icon = 'Logo.png';
            app.ContinuousSpikeAnalysisUIFigure.CloseRequestFcn = createCallbackFcn(app, @ContinuousSpikeAnalysisUIFigureCloseRequest, true);
            app.SnapshotMenu = uimenu(app.ContinuousSpikeAnalysisUIFigure);
            app.SnapshotMenu.Text = '   Snapshot   ';
            app.SaveSnapshotofWindowMenu = uimenu(app.SnapshotMenu);
            app.SaveSnapshotofWindowMenu.Text = 'Save Snapshot of Window';
            app.PNGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.PNGMenu.MenuSelectedFcn = createCallbackFcn(app, @PNGMenuSelected, true);
            app.PNGMenu.Text = 'PNG';
            app.JPGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.JPGMenu.MenuSelectedFcn = createCallbackFcn(app, @JPGMenuSelected, true);
            app.JPGMenu.Text = 'JPG';
            app.SVGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.SVGMenu.MenuSelectedFcn = createCallbackFcn(app, @SVGMenuSelected, true);
            app.SVGMenu.Text = 'SVG';
            app.PlotMenu = uimenu(app.ContinuousSpikeAnalysisUIFigure);
            app.PlotMenu.Text = '   Plot   ';
            app.Show2DimensionalPlotsMenu = uimenu(app.PlotMenu);
            app.Show2DimensionalPlotsMenu.MenuSelectedFcn = createCallbackFcn(app, @Show2DimensionalPlotsMenuSelected, true);
            app.Show2DimensionalPlotsMenu.Text = 'Show 2 Dimensional Plots';
            app.Show3DimensionalPlotsMenu = uimenu(app.PlotMenu);
            app.Show3DimensionalPlotsMenu.MenuSelectedFcn = createCallbackFcn(app, @Show3DimensionalPlotsMenuSelected, true);
            app.Show3DimensionalPlotsMenu.Text = 'Show 3 Dimensional Plots';
            app.ChangePlotPropertiesMenu = uimenu(app.PlotMenu);
            app.ChangePlotPropertiesMenu.Text = 'Change Plot Properties';
            app.SpikeDataPlotMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.SpikeDataPlotMenu.Text = 'Spike Data Plot';
            app.FontSizeMenu = uimenu(app.SpikeDataPlotMenu);
            app.FontSizeMenu.MenuSelectedFcn = createCallbackFcn(app, @FontSizeMenuSelected, true);
            app.FontSizeMenu.Text = 'FontSize';
            app.LineWidthSpikesMenu = uimenu(app.SpikeDataPlotMenu);
            app.LineWidthSpikesMenu.MenuSelectedFcn = createCallbackFcn(app, @LineWidthSpikesMenuSelected, true);
            app.LineWidthSpikesMenu.Text = 'LineWidth Spikes';
            app.LineWidthTriggerMenu = uimenu(app.SpikeDataPlotMenu);
            app.LineWidthTriggerMenu.MenuSelectedFcn = createCallbackFcn(app, @LineWidthTriggerMenuSelected, true);
            app.LineWidthTriggerMenu.Text = 'LineWidth Trigger';
            app.ColorSpikesMenu = uimenu(app.SpikeDataPlotMenu);
            app.ColorSpikesMenu.MenuSelectedFcn = createCallbackFcn(app, @ColorSpikesMenuSelected, true);
            app.ColorSpikesMenu.Text = 'Color Spikes';
            app.ColorTriggerMenu = uimenu(app.SpikeDataPlotMenu);
            app.ColorTriggerMenu.MenuSelectedFcn = createCallbackFcn(app, @ColorTriggerMenuSelected, true);
            app.ColorTriggerMenu.Text = 'Color Trigger';
            app.BackgorundColorMenu = uimenu(app.SpikeDataPlotMenu);
            app.BackgorundColorMenu.MenuSelectedFcn = createCallbackFcn(app, @BackgorundColorMenuSelected, true);
            app.BackgorundColorMenu.Text = 'Backgorund Color';
            app.XLabelMenu = uimenu(app.SpikeDataPlotMenu);
            app.XLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @XLabelMenuSelected, true);
            app.XLabelMenu.Text = 'XLabel';
            app.YLabelMenu = uimenu(app.SpikeDataPlotMenu);
            app.YLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @YLabelMenuSelected, true);
            app.YLabelMenu.Text = 'YLabel';
            app.RESETMenu = uimenu(app.SpikeDataPlotMenu);
            app.RESETMenu.MenuSelectedFcn = createCallbackFcn(app, @RESETMenuSelected, true);
            app.RESETMenu.Text = 'RESET';
            app.SpikeRatePlotMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.SpikeRatePlotMenu.Text = 'Spike Rate Plot';
            app.SpikeRateoverTimeMenu = uimenu(app.SpikeRatePlotMenu);
            app.SpikeRateoverTimeMenu.Text = 'Spike Rate over Time';
            app.FontSizeMenu_2 = uimenu(app.SpikeRateoverTimeMenu);
            app.FontSizeMenu_2.MenuSelectedFcn = createCallbackFcn(app, @FontSizeMenu_2Selected, true);
            app.FontSizeMenu_2.Text = 'FontSize';
            app.BarColorMenu = uimenu(app.SpikeRateoverTimeMenu);
            app.BarColorMenu.MenuSelectedFcn = createCallbackFcn(app, @BarColorMenuSelected, true);
            app.BarColorMenu.Text = 'Bar Color';
            app.BackgroundColorMenu = uimenu(app.SpikeRateoverTimeMenu);
            app.BackgroundColorMenu.MenuSelectedFcn = createCallbackFcn(app, @BackgroundColorMenuSelected, true);
            app.BackgroundColorMenu.Text = 'Background Color';
            app.XLabelMenu_2 = uimenu(app.SpikeRateoverTimeMenu);
            app.XLabelMenu_2.MenuSelectedFcn = createCallbackFcn(app, @XLabelMenu_2Selected, true);
            app.XLabelMenu_2.Text = 'XLabel';
            app.YLabelMenu_2 = uimenu(app.SpikeRateoverTimeMenu);
            app.YLabelMenu_2.MenuSelectedFcn = createCallbackFcn(app, @YLabelMenu_2Selected, true);
            app.YLabelMenu_2.Text = 'YLabel';
            app.RESETMenu_2 = uimenu(app.SpikeRateoverTimeMenu);
            app.RESETMenu_2.MenuSelectedFcn = createCallbackFcn(app, @RESETMenu_2Selected, true);
            app.RESETMenu_2.Text = 'RESET';
            app.SpikeRateoverDepthMenu = uimenu(app.SpikeRatePlotMenu);
            app.SpikeRateoverDepthMenu.Text = 'Spike Rate over Depth';
            app.FontSizeMenu_3 = uimenu(app.SpikeRateoverDepthMenu);
            app.FontSizeMenu_3.MenuSelectedFcn = createCallbackFcn(app, @FontSizeMenu_3Selected, true);
            app.FontSizeMenu_3.Text = 'FontSize';
            app.BarColorMenu_2 = uimenu(app.SpikeRateoverDepthMenu);
            app.BarColorMenu_2.MenuSelectedFcn = createCallbackFcn(app, @BarColorMenu_2Selected, true);
            app.BarColorMenu_2.Text = 'Bar Color';
            app.BackgroundColorMenu_2 = uimenu(app.SpikeRateoverDepthMenu);
            app.BackgroundColorMenu_2.MenuSelectedFcn = createCallbackFcn(app, @BackgroundColorMenu_2Selected, true);
            app.BackgroundColorMenu_2.Text = 'Background Color';
            app.XLabelMenu_3 = uimenu(app.SpikeRateoverDepthMenu);
            app.XLabelMenu_3.MenuSelectedFcn = createCallbackFcn(app, @XLabelMenu_3Selected, true);
            app.XLabelMenu_3.Text = 'XLabel';
            app.YLabelMenu_3 = uimenu(app.SpikeRateoverDepthMenu);
            app.YLabelMenu_3.MenuSelectedFcn = createCallbackFcn(app, @YLabelMenu_3Selected, true);
            app.YLabelMenu_3.Text = 'YLabel';
            app.RESETMenu_3 = uimenu(app.SpikeRateoverDepthMenu);
            app.RESETMenu_3.MenuSelectedFcn = createCallbackFcn(app, @RESETMenu_3Selected, true);
            app.RESETMenu_3.Text = 'RESET';
            app.SaveCustomeAppearanceMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.SaveCustomeAppearanceMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveCustomeAppearanceMenuSelected, true);
            app.SaveCustomeAppearanceMenu.Text = 'Save Custome Appearance';
            app.DeleteCustomeAppearanceMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.DeleteCustomeAppearanceMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteCustomeAppearanceMenuSelected, true);
            app.DeleteCustomeAppearanceMenu.Text = 'Delete Custome Appearance';
            app.ExportDataMenu = uimenu(app.ContinuousSpikeAnalysisUIFigure);
            app.ExportDataMenu.Text = '   Export Data   ';
            app.SpikeRateMenu = uimenu(app.ExportDataMenu);
            app.SpikeRateMenu.MenuSelectedFcn = createCallbackFcn(app, @SpikeRateMenuSelected, true);
            app.SpikeRateMenu.Text = 'Spike Rate';
            app.AllSpikesMenu = uimenu(app.SpikeRateMenu);
            app.AllSpikesMenu.Text = 'All Spikes';
            app.OverTimeMenu = uimenu(app.AllSpikesMenu);
            app.OverTimeMenu.MenuSelectedFcn = createCallbackFcn(app, @OverTimeMenuSelected, true);
            app.OverTimeMenu.Text = 'Over Time';
            app.matMenu_5 = uimenu(app.OverTimeMenu);
            app.matMenu_5.MenuSelectedFcn = createCallbackFcn(app, @matMenu_5Selected, true);
            app.matMenu_5.Text = '.mat';
            app.txtMenu_6 = uimenu(app.OverTimeMenu);
            app.txtMenu_6.MenuSelectedFcn = createCallbackFcn(app, @txtMenu_6Selected, true);
            app.txtMenu_6.Text = '.txt';
            app.xlsxMenu = uimenu(app.OverTimeMenu);
            app.xlsxMenu.MenuSelectedFcn = createCallbackFcn(app, @xlsxMenuSelected, true);
            app.xlsxMenu.Text = '.xlsx';
            app.OverChannelMenu = uimenu(app.AllSpikesMenu);
            app.OverChannelMenu.MenuSelectedFcn = createCallbackFcn(app, @OverChannelMenuSelected, true);
            app.OverChannelMenu.Text = 'Over Channel';
            app.matMenu_6 = uimenu(app.OverChannelMenu);
            app.matMenu_6.MenuSelectedFcn = createCallbackFcn(app, @matMenu_6Selected, true);
            app.matMenu_6.Text = '.mat';
            app.txtMenu_7 = uimenu(app.OverChannelMenu);
            app.txtMenu_7.MenuSelectedFcn = createCallbackFcn(app, @txtMenu_7Selected, true);
            app.txtMenu_7.Text = '.txt';
            app.xlsxMenu_4 = uimenu(app.OverChannelMenu);
            app.xlsxMenu_4.MenuSelectedFcn = createCallbackFcn(app, @xlsxMenu_4Selected, true);
            app.xlsxMenu_4.Text = '.xlsx';
            app.SelectedUnitMenu = uimenu(app.SpikeRateMenu);
            app.SelectedUnitMenu.Text = 'Selected Unit';
            app.matMenu_2 = uimenu(app.SelectedUnitMenu);
            app.matMenu_2.MenuSelectedFcn = createCallbackFcn(app, @matMenu_2Selected, true);
            app.matMenu_2.Text = '.mat';
            app.txtMenu_3 = uimenu(app.SelectedUnitMenu);
            app.txtMenu_3.MenuSelectedFcn = createCallbackFcn(app, @txtMenu_3Selected, true);
            app.txtMenu_3.Text = '.txt';
            app.xlsxMenu_5 = uimenu(app.SelectedUnitMenu);
            app.xlsxMenu_5.MenuSelectedFcn = createCallbackFcn(app, @xlsxMenu_5Selected, true);
            app.xlsxMenu_5.Text = '.xlsx';
            app.MainPlotMenu = uimenu(app.ExportDataMenu);
            app.MainPlotMenu.MenuSelectedFcn = createCallbackFcn(app, @MainPlotMenuSelected, true);
            app.MainPlotMenu.Text = 'Main Plot';
            app.AllSpikesMenu_2 = uimenu(app.MainPlotMenu);
            app.AllSpikesMenu_2.Text = 'All Spikes';
            app.matMenu_3 = uimenu(app.AllSpikesMenu_2);
            app.matMenu_3.MenuSelectedFcn = createCallbackFcn(app, @matMenu_3Selected, true);
            app.matMenu_3.Text = '.mat';
            app.txtMenu_4 = uimenu(app.AllSpikesMenu_2);
            app.txtMenu_4.MenuSelectedFcn = createCallbackFcn(app, @txtMenu_4Selected, true);
            app.txtMenu_4.Text = '.txt';
            app.xlsxMenu_2 = uimenu(app.AllSpikesMenu_2);
            app.xlsxMenu_2.MenuSelectedFcn = createCallbackFcn(app, @xlsxMenu_2Selected, true);
            app.xlsxMenu_2.Text = '.xlsx';
            app.SelectedUnitMenu_2 = uimenu(app.MainPlotMenu);
            app.SelectedUnitMenu_2.Text = 'Selected Unit';
            app.matMenu_4 = uimenu(app.SelectedUnitMenu_2);
            app.matMenu_4.MenuSelectedFcn = createCallbackFcn(app, @matMenu_4Selected, true);
            app.matMenu_4.Text = '.mat';
            app.txtMenu_5 = uimenu(app.SelectedUnitMenu_2);
            app.txtMenu_5.MenuSelectedFcn = createCallbackFcn(app, @txtMenu_5Selected, true);
            app.txtMenu_5.Text = '.txt';
            app.xlsxMenu_3 = uimenu(app.SelectedUnitMenu_2);
            app.xlsxMenu_3.MenuSelectedFcn = createCallbackFcn(app, @xlsxMenu_3Selected, true);
            app.xlsxMenu_3.Text = '.xlsx';
            app.UIAxes_5 = uiaxes(app.ContinuousSpikeAnalysisUIFigure);
            xlabel(app.UIAxes_5, 'X')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes = uiaxes(app.ContinuousSpikeAnalysisUIFigure);
            title(app.UIAxes, 'Title')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes_3 = uiaxes(app.ContinuousSpikeAnalysisUIFigure);
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.SpikeAnalysisOptionsPanel = uipanel(app.ContinuousSpikeAnalysisUIFigure);
            app.SpikeAnalysisOptionsPanel.Title = 'Spike Analysis Options';
            app.TypeofAnalysisLabel = uilabel(app.SpikeAnalysisOptionsPanel);
            app.TypeofAnalysisLabel.Text = 'Type of Analysis';
            app.TypeofAnalysisDropDown = uidropdown(app.SpikeAnalysisOptionsPanel);
            app.TypeofAnalysisDropDown.Items = {''};
            app.TypeofAnalysisDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeofAnalysisDropDownValueChanged, true);
            app.TypeofAnalysisDropDown.Value = '';
            app.ClustertoShowDropDownLabel = uilabel(app.SpikeAnalysisOptionsPanel);
            app.ClustertoShowDropDownLabel.Text = 'Cluster to Show';
            app.ClustertoshowDropDown = uidropdown(app.SpikeAnalysisOptionsPanel);
            app.ClustertoshowDropDown.Items = {''};
            app.ClustertoshowDropDown.ValueChangedFcn = createCallbackFcn(app, @ClustertoshowDropDownValueChanged, true);
            app.ClustertoshowDropDown.Value = '';
            app.EventstoShowDropDownLabel = uilabel(app.SpikeAnalysisOptionsPanel);
            app.EventstoShowDropDownLabel.Text = 'Events to Show';
            app.EventstoshowDropDown = uidropdown(app.SpikeAnalysisOptionsPanel);
            app.EventstoshowDropDown.Items = {};
            app.EventstoshowDropDown.ValueChangedFcn = createCallbackFcn(app, @EventstoshowDropDownValueChanged, true);
            app.EventstoshowDropDown.Value = {};
            app.SpikeRateNumBinsEditFieldLabel = uilabel(app.SpikeAnalysisOptionsPanel);
            app.SpikeRateNumBinsEditFieldLabel.Text = {'Spike Rate'; 'Num Bins'};
            app.SpikeRateNumBinsEditField = uieditfield(app.SpikeAnalysisOptionsPanel, 'text');
            app.SpikeRateNumBinsEditField.ValueChangedFcn = createCallbackFcn(app, @SpikeRateNumBinsEditFieldValueChanged, true);
            app.SpikeRateNumBinsEditField.Value = '200';
            app.TimeWindowSpiketriggredLFPfromtoEditFieldLabel = uilabel(app.SpikeAnalysisOptionsPanel);
            app.TimeWindowSpiketriggredLFPfromtoEditFieldLabel.Text = {'Time Window '; 'Spike triggred LFP'; '(from,to)'};
            app.TimeWindowSpiketriggredLFPEditField = uieditfield(app.SpikeAnalysisOptionsPanel, 'text');
            app.TimeWindowSpiketriggredLFPEditField.ValueChangedFcn = createCallbackFcn(app, @TimeWindowSpiketriggredLFPEditFieldValueChanged, true);
            app.TimeWindowSpiketriggredLFPEditField.Value = '0.005,0.2';
            app.SpikeAnalysisOptionsLabel = uilabel(app.SpikeAnalysisOptionsPanel);
            app.SpikeAnalysisOptionsLabel.Text = 'Spike Analysis Options';
            app.CloseButton = uibutton(app.ContinuousSpikeAnalysisUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.WaveformOptionsPanel = uipanel(app.ContinuousSpikeAnalysisUIFigure);
            app.WaveformOptionsPanel.Title = 'Waveform Options';
            app.WaveformSelectionforPlottingEditFieldLabel = uilabel(app.WaveformOptionsPanel);
            app.WaveformSelectionforPlottingEditFieldLabel.Text = {'Waveform Selection'; 'for Plotting'};
            app.WaveformSelectionforPlottingEditField = uieditfield(app.WaveformOptionsPanel, 'text');
            app.WaveformSelectionforPlottingEditField.ValueChangedFcn = createCallbackFcn(app, @WaveformSelectionforPlottingEditFieldValueChanged, true);
            app.WaveformSelectionforPlottingEditField.Value = '1,100';
            app.WaveformOptionsLabel = uilabel(app.WaveformOptionsPanel);
            app.WaveformOptionsLabel.Text = 'Waveform Options';
            app.ChannelSelectionforPlottingEditFieldLabel = uilabel(app.ContinuousSpikeAnalysisUIFigure);
            app.ChannelSelectionforPlottingEditFieldLabel.Enable = 'off';
            app.ChannelSelectionforPlottingEditFieldLabel.Visible = 'off';
            app.ChannelSelectionforPlottingEditFieldLabel.Text = {'Channel Selection'; 'for Plotting'};
            app.ChannelSelectionforPlottingEditField = uieditfield(app.ContinuousSpikeAnalysisUIFigure, 'text');
            app.ChannelSelectionforPlottingEditField.ValueChangedFcn = createCallbackFcn(app, @ChannelSelectionforPlottingEditFieldValueChanged, true);
            app.ChannelSelectionforPlottingEditField.Editable = 'off';
            app.ChannelSelectionforPlottingEditField.Enable = 'off';
            app.ChannelSelectionforPlottingEditField.Visible = 'off';
            app.TextArea = uitextarea(app.ContinuousSpikeAnalysisUIFigure);
            app.Button = uibutton(app.ContinuousSpikeAnalysisUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'Open_Figure_Icon.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.ContinuousSpikeAnalysisUIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'Open_Figure_Icon.png';
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.ContinuousSpikeAnalysisUIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = 'Open_Figure_Icon.png';
            app.Button_3.Text = '';
            app.ContextMenu = uicontextmenu(app.ContinuousSpikeAnalysisUIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.ClustertoshowDropDown.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.ContinuousSpikeAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Continuous_Spike_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ContinuousSpikeAnalysisUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ContinuousSpikeAnalysisUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ContinuousSpikeAnalysisUIFigure)
        end
    end
end