classdef Continuous_Unit_Analysis_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UnitAnalysisWindowUIFigure    matlab.ui.Figure
        SnapshotMenu                  matlab.ui.container.Menu
        SaveSnapshotofWindowMenu      matlab.ui.container.Menu
        PNGMenu                       matlab.ui.container.Menu
        JPGMenu                       matlab.ui.container.Menu
        SVGMenu                       matlab.ui.container.Menu
        WaveformsMenu_2               matlab.ui.container.Menu
        LeftPlotMenu_4                matlab.ui.container.Menu
        RightPlotMenu_4               matlab.ui.container.Menu
        ISIMenu_2                     matlab.ui.container.Menu
        LeftPlotMenu_5                matlab.ui.container.Menu
        RightPlotMenu_5               matlab.ui.container.Menu
        AutocorrelogramMenu_2         matlab.ui.container.Menu
        LeftPlotMenu_6                matlab.ui.container.Menu
        RightPlotMenu_6               matlab.ui.container.Menu
        EPSMenu                       matlab.ui.container.Menu
        WaveformsMenu_3               matlab.ui.container.Menu
        LeftPlotMenu_7                matlab.ui.container.Menu
        RightPlotMenu_7               matlab.ui.container.Menu
        ISIMenu_3                     matlab.ui.container.Menu
        LeftPlotMenu_8                matlab.ui.container.Menu
        RightPlotMenu_8               matlab.ui.container.Menu
        AutocorrelogramMenu_3         matlab.ui.container.Menu
        LeftPlotMenu_9                matlab.ui.container.Menu
        RightPlotMenu_9               matlab.ui.container.Menu
        ExportDataMenu                matlab.ui.container.Menu
        WaveformsMenu                 matlab.ui.container.Menu
        LeftPlotMenu                  matlab.ui.container.Menu
        matMenu                       matlab.ui.container.Menu
        txtMenu                       matlab.ui.container.Menu
        xlsxMenu                      matlab.ui.container.Menu
        RightPlotMenu                 matlab.ui.container.Menu
        matMenu_2                     matlab.ui.container.Menu
        txtMenu_2                     matlab.ui.container.Menu
        xlsxMenu_2                    matlab.ui.container.Menu
        ISIMenu                       matlab.ui.container.Menu
        LeftPlotMenu_2                matlab.ui.container.Menu
        matMenu_4                     matlab.ui.container.Menu
        txtMenu_4                     matlab.ui.container.Menu
        xlsxMenu_3                    matlab.ui.container.Menu
        RightPlotMenu_2               matlab.ui.container.Menu
        matMenu_5                     matlab.ui.container.Menu
        txtMenu_5                     matlab.ui.container.Menu
        xlsxMenu_4                    matlab.ui.container.Menu
        AutocorrelogramMenu           matlab.ui.container.Menu
        LeftPlotMenu_3                matlab.ui.container.Menu
        matMenu_7                     matlab.ui.container.Menu
        txtMenu_7                     matlab.ui.container.Menu
        xlsxMenu_5                    matlab.ui.container.Menu
        RightPlotMenu_3               matlab.ui.container.Menu
        matMenu_8                     matlab.ui.container.Menu
        txtMenu_8                     matlab.ui.container.Menu
        xlsxMenu_6                    matlab.ui.container.Menu
        CloseButton                   matlab.ui.control.Button
        PlotColumnSettingsLabel       matlab.ui.control.Label
        GeneralSettingsPanel          matlab.ui.container.Panel
        GeneralSettingsLabel          matlab.ui.control.Label
        MaxISItoshowsEditField        matlab.ui.control.EditField
        MaxISItoshowsLabel            matlab.ui.control.Label
        DeleteUnitButton              matlab.ui.control.Button
        TimeLagmsEditField            matlab.ui.control.EditField
        TimeLagmsEditFieldLabel       matlab.ui.control.Label
        DeleteUnitDropDown            matlab.ui.control.DropDown
        DeleteUnitDropDownLabel       matlab.ui.control.Label
        BinNumberISIEditField         matlab.ui.control.EditField
        BinNumberISILabel             matlab.ui.control.Label
        PlotColumnSettingsPanel       matlab.ui.container.Panel
        NumWaveformsEditField_2       matlab.ui.control.EditField
        NumWaveformsEditField_2Label  matlab.ui.control.Label
        UnitstoShowEditField_2        matlab.ui.control.EditField
        UnittoShowEditFieldLabel_2    matlab.ui.control.Label
        UnitstoShowEditField          matlab.ui.control.EditField
        UnittoShowEditFieldLabel      matlab.ui.control.Label
        NumWaveformsEditField         matlab.ui.control.EditField
        NumWaveformsEditFieldLabel    matlab.ui.control.Label
        UIAxes_7                      matlab.ui.control.UIAxes
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes_5                      matlab.ui.control.UIAxes
        UIAxes_8                      matlab.ui.control.UIAxes
        UIAxes_1                      matlab.ui.control.UIAxes
        UIAxes_4                      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Mainapp = []; % Description
        AnalysisWindow = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp, AnalysisWindow)
            movegui(app.UnitAnalysisWindowUIFigure, 'center');
            
            app.Mainapp = Mainapp;
            app.AnalysisWindow = AnalysisWindow;
            
            app = Utility_Change_Light_Dark_Mode(app,"ContUnitAnalysis");
            
            TempCluster = app.Mainapp.Data.Spikes.SpikeCluster;

            if min(TempCluster)==0
                TempCluster = TempCluster + 1;
            end

            uniqueCluster = unique(TempCluster);

            for i = 1:length(unique(TempCluster))
                currentcluster = uniqueCluster(i);
                app.DeleteUnitDropDown.Items{i} = convertStringsToChars(strcat("Unit"," ",num2str(currentcluster)));
            end

            app.NumWaveformsEditField.Value = "20";
            app.NumWaveformsEditField_2.Value = "20";
            
            if length(uniqueCluster)>=4
                app.UnitstoShowEditField.Value = strcat(num2str(uniqueCluster(1)),",",num2str(uniqueCluster(2)));
                app.UnitstoShowEditField_2.Value = strcat(num2str(uniqueCluster(3)),",",num2str(uniqueCluster(4)));
            elseif length(uniqueCluster)==2
                app.UnitstoShowEditField.Value = strcat(num2str(uniqueCluster(1)));
                app.UnitstoShowEditField_2.Value = strcat(num2str(uniqueCluster(2)));
            elseif length(uniqueCluster)==3
                app.UnitstoShowEditField.Value = strcat(num2str(uniqueCluster(1)),",",num2str(uniqueCluster(2)));
                app.UnitstoShowEditField_2.Value = strcat(num2str(uniqueCluster(3)));
            elseif isscalar(uniqueCluster)
                app.UnitstoShowEditField.Value = strcat(num2str(uniqueCluster(1)));
                app.UnitstoShowEditField_2.Value = strcat(num2str(uniqueCluster(1)));
            else
                app.UnitstoShowEditField.Value = '';
            end
            
            [Units,Waves,Wavefigs,ISIfigs,AutoCfigs,SpikeTimes,SpikePositions,SpikeCluster,SpikeWaveforms,SpikeChannel,app.TimeLagmsEditField.Value] = Spike_Module_Prepare_WaveForm_Window_and_Analysis(app.Mainapp.Data,app.UnitstoShowEditField.Value,app.UnitstoShowEditField_2.Value,app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.UIAxes_1,app.UIAxes_2,app.UIAxes_4,app.UIAxes_5,app.UIAxes_7,app.UIAxes_8,"StartUp",app.AnalysisWindow,app.TimeLagmsEditField.Value);

            %% Plot Waveforms
           
            app.Mainapp.CurrentPlotData = Spike_Waveforms_Plot_Waveforms(app.Mainapp.Data,Units,SpikeWaveforms,SpikeCluster,Waves,Wavefigs,app.Mainapp.CurrentPlotData);

            %% Plot ISI
            
            [~,app.Mainapp.CurrentPlotData] = Spike_Module_Calculate_Plot_ISI(app.Mainapp.Data,SpikeTimes,SpikePositions,SpikeCluster,SpikeChannel,Units,Waves,ISIfigs,str2double(app.BinNumberISIEditField.Value),str2double(app.MaxISItoshowsEditField.Value),app.Mainapp.CurrentPlotData);
      
            %% Plot Autocorrelogramme

            app.Mainapp.CurrentPlotData = Spikes_Module_AutoCorrelogram(app.Mainapp.Data,SpikeTimes,SpikePositions,SpikeChannel,SpikeCluster,AutoCfigs,Units,str2double(app.BinNumberISIEditField.Value),app.Mainapp.CurrentPlotData,str2double(app.TimeLagmsEditField.Value));
      
        end

        % Callback function
        function CloseButtonPushed(app, event)
            
        end

        % Callback function
        function UnitstoShowEditFieldValueChanged(app, event)
            
      
        end

        % Callback function
        function NumWaveformsEditFieldValueChanged(app, event)
            

        end

        % Callback function
        function NumWaveformsEditField_2ValueChanged(app, event)
            

        end

        % Callback function
        function UnitstoShowEditField_2ValueChanged(app, event)
            
            
        end

        % Menu selected function: PNGMenu
        function PNGMenuSelected(app, event)
            [file, path] = uiputfile('*.png', 'Save as');

            exportapp(app.UnitAnalysisWindowUIFigure,fullfile(path,file)) 
        end

        % Menu selected function: JPGMenu
        function JPGMenuSelected(app, event)
            [file, path] = uiputfile('*.jpg', 'Save as');

            exportapp(app.UnitAnalysisWindowUIFigure,fullfile(path,file)) 
        end

        % Callback function
        function MaxISItoshowsEditFieldValueChanged(app, event)
            
         
        end

        % Callback function
        function BinNumberISIEditFieldValueChanged(app, event)
            
            
        end

        % Callback function
        function DeleteUnitButtonPushed(app, event)


        end

        % Menu selected function: matMenu
        function matMenuSelected(app, event)
            TimeRangePlot = size(app.Mainapp.Data.Spikes.Waveforms,2)/app.Mainapp.Data.Info.NativeSamplingRate;
            
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continuous Unit(s) ",app.UnitstoShowEditField.Value," Waveform Analysis Plot ",num2str(1)),0);
        end

        % Menu selected function: txtMenu
        function txtMenuSelected(app, event)
            TimeRangePlot = size(app.Mainapp.Data.Spikes.Waveforms,2)/app.Mainapp.Data.Info.NativeSamplingRate;
            
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continuous Unit(s) ",app.UnitstoShowEditField.Value," Waveform Analysis Plot ",num2str(1)),0);
        end

        % Menu selected function: matMenu_2
        function matMenu_2Selected(app, event)
            TimeRangePlot = size(app.Mainapp.Data.Spikes.Waveforms,2)/app.Mainapp.Data.Info.NativeSamplingRate;
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continuous Unit(s) ",app.UnitstoShowEditField_2.Value," Waveform Analysis Plot ",num2str(2)),0);
        end

        % Menu selected function: txtMenu_2
        function txtMenu_2Selected(app, event)
            TimeRangePlot = size(app.Mainapp.Data.Spikes.Waveforms,2)/app.Mainapp.Data.Info.NativeSamplingRate;
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continuous Unit(s) ",app.UnitstoShowEditField_2.Value," Waveform Analysis Plot ",num2str(2)),0);
        end

        % Menu selected function: matMenu_4
        function matMenu_4Selected(app, event)
            TimeRangePlot = size(app.Mainapp.Data.Spikes.Waveforms,2)/app.Mainapp.Data.Info.NativeSamplingRate;
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continuous Unit(s) ",app.UnitstoShowEditField.Value," ISI Analysis Plot ",num2str(1)),0);
        end

        % Menu selected function: txtMenu_4
        function txtMenu_4Selected(app, event)
            TimeRangePlot = size(app.Mainapp.Data.Spikes.Waveforms,2)/app.Mainapp.Data.Info.NativeSamplingRate;
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continuous Unit(s) ",app.UnitstoShowEditField.Value," ISI Analysis Plot ",num2str(1)),0);
        end

        % Menu selected function: matMenu_5
        function matMenu_5Selected(app, event)
            TimeRangePlot = size(app.Mainapp.Data.Spikes.Waveforms,2)/app.Mainapp.Data.Info.NativeSamplingRate;
         
            StartTime = 0;
            
            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continuous Unit(s) ",app.UnitstoShowEditField_2.Value," ISI Analysis Plot ",num2str(2)),0);
        end

        % Menu selected function: txtMenu_5
        function txtMenu_5Selected(app, event)
            TimeRangePlot = size(app.Mainapp.Data.Spikes.Waveforms,2)/app.Mainapp.Data.Info.NativeSamplingRate;
         
            StartTime = 0;
            
            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continuous Unit(s) ",app.UnitstoShowEditField_2.Value," ISI Analysis Plot ",num2str(2)),0);
        end

        % Menu selected function: matMenu_7
        function matMenu_7Selected(app, event)
            TimeRangePlot = size(app.Mainapp.Data.Spikes.Waveforms,2)/app.Mainapp.Data.Info.NativeSamplingRate;
         
            StartTime = 0;
            
            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continuous Unit(s) ",app.UnitstoShowEditField.Value," Autocorrelogram Analysis Plot ",num2str(1)),0);
        end

        % Menu selected function: txtMenu_7
        function txtMenu_7Selected(app, event)
            TimeRangePlot = size(app.Mainapp.Data.Spikes.Waveforms,2)/app.Mainapp.Data.Info.NativeSamplingRate;
         
            StartTime = 0;
            
            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continuous Unit(s) ",app.UnitstoShowEditField.Value," Autocorrelogram Analysis Plot ",num2str(1)),0);
        end

        % Menu selected function: matMenu_8
        function matMenu_8Selected(app, event)
            TimeRangePlot = size(app.Mainapp.Data.Spikes.Waveforms,2)/app.Mainapp.Data.Info.NativeSamplingRate;
         
            StartTime = 0;
            
            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continuous Unit(s) ",app.UnitstoShowEditField_2.Value," Autocorrelogram Analysis Plot ",num2str(2)),0);
        end

        % Menu selected function: txtMenu_8
        function txtMenu_8Selected(app, event)
            TimeRangePlot = size(app.Mainapp.Data.Spikes.Waveforms,2)/app.Mainapp.Data.Info.NativeSamplingRate;
         
            StartTime = 0;
            
            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continuous Unit(s) ",app.UnitstoShowEditField_2.Value," Autocorrelogram Analysis Plot ",num2str(2)),0);
        end

        % Callback function
        function TimeLagmsEditFieldValueChanged(app, event)


        end

        % Close request function: UnitAnalysisWindowUIFigure
        function UnitAnalysisWindowUIFigureCloseRequest(app, event)
            app.Mainapp.UnitAnalysis = [];

            if ~isempty(app.Mainapp.ProbeViewWindowHandle) % delete option from probe view 
                for i = 1:length(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                    if strcmp(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Unit Analysis Window')
                        app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items(i) = [];
                        break;
                    end
                end
            else % if no probe view available
 
            end

            delete(app)
            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed2(app, event)
            app.Mainapp.UnitAnalysis = [];

            if ~isempty(app.Mainapp.ProbeViewWindowHandle) % delete option from probe view 
                for i = 1:length(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                    if strcmp(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Unit Analysis Window')
                        app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items(i) = [];
                        break;
                    end
                end
            else % if no probe view available
 
            end

            delete(app)
        end

        % Value changed function: UnitstoShowEditField
        function UnitstoShowEditFieldValueChanged2(app, event)
            %% Check Inputs
            
            [app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.BinNumberISIEditField.Value,app.MaxISItoshowsEditField.Value] = Spike_Module_Prepare_WaveForm_Window_and_Analysis_Check_Inputs(app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.BinNumberISIEditField.Value,app.MaxISItoshowsEditField.Value);

            %% Prepare analyiss and plots
            [Units,Waves,Wavefigs,ISIfigs,AutoCfigs,SpikeTimes,SpikePositions,SpikeCluster,SpikeWaveforms,SpikeChannel,app.TimeLagmsEditField.Value] = Spike_Module_Prepare_WaveForm_Window_and_Analysis(app.Mainapp.Data,app.UnitstoShowEditField.Value,app.UnitstoShowEditField_2.Value,app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.UIAxes_1,app.UIAxes_2,app.UIAxes_4,app.UIAxes_5,app.UIAxes_7,app.UIAxes_8,"U1",app.AnalysisWindow,app.TimeLagmsEditField.Value);
            
            %% Plot Waveforms
           
            app.Mainapp.CurrentPlotData = Spike_Waveforms_Plot_Waveforms(app.Mainapp.Data,Units,SpikeWaveforms,SpikeCluster,Waves,Wavefigs,app.Mainapp.CurrentPlotData);

            %% Plot ISI
            
            [~,app.Mainapp.CurrentPlotData] = Spike_Module_Calculate_Plot_ISI(app.Mainapp.Data,SpikeTimes,SpikePositions,SpikeCluster,SpikeChannel,Units,Waves,ISIfigs,str2double(app.BinNumberISIEditField.Value),str2double(app.MaxISItoshowsEditField.Value),app.Mainapp.CurrentPlotData);
      
            %% Plot Autocorrelogramme

            app.Mainapp.CurrentPlotData = Spikes_Module_AutoCorrelogram(app.Mainapp.Data,SpikeTimes,SpikePositions,SpikeChannel,SpikeCluster,AutoCfigs,Units,str2double(app.BinNumberISIEditField.Value),app.Mainapp.CurrentPlotData,str2double(app.TimeLagmsEditField.Value));
            
        end

        % Value changed function: NumWaveformsEditField
        function NumWaveformsEditFieldValueChanged2(app, event)
            %% Check Inputs
            
            [app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.BinNumberISIEditField.Value,app.MaxISItoshowsEditField.Value] = Spike_Module_Prepare_WaveForm_Window_and_Analysis_Check_Inputs(app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.BinNumberISIEditField.Value,app.MaxISItoshowsEditField.Value);

            [Units,Waves,Wavefigs,ISIfigs,AutoCfigs,SpikeTimes,SpikePositions,SpikeCluster,SpikeWaveforms,SpikeChannel,app.TimeLagmsEditField.Value] = Spike_Module_Prepare_WaveForm_Window_and_Analysis(app.Mainapp.Data,app.UnitstoShowEditField.Value,app.UnitstoShowEditField_2.Value,app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.UIAxes_1,app.UIAxes_2,app.UIAxes_4,app.UIAxes_5,app.UIAxes_7,app.UIAxes_8,"W1",app.AnalysisWindow,app.TimeLagmsEditField.Value);
            
            %% Plot Waveforms
           
            app.Mainapp.CurrentPlotData = Spike_Waveforms_Plot_Waveforms(app.Mainapp.Data,Units,SpikeWaveforms,SpikeCluster,Waves,Wavefigs,app.Mainapp.CurrentPlotData);
            
        end

        % Value changed function: NumWaveformsEditField_2
        function NumWaveformsEditField_2ValueChanged2(app, event)
            %% Check Inputs
            
            [app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.BinNumberISIEditField.Value,app.MaxISItoshowsEditField.Value] = Spike_Module_Prepare_WaveForm_Window_and_Analysis_Check_Inputs(app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.BinNumberISIEditField.Value,app.MaxISItoshowsEditField.Value);


            [Units,Waves,Wavefigs,ISIfigs,AutoCfigs,SpikeTimes,SpikePositions,SpikeCluster,SpikeWaveforms,SpikeChannel,app.TimeLagmsEditField.Value] = Spike_Module_Prepare_WaveForm_Window_and_Analysis(app.Mainapp.Data,app.UnitstoShowEditField.Value,app.UnitstoShowEditField_2.Value,app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.UIAxes_1,app.UIAxes_2,app.UIAxes_4,app.UIAxes_5,app.UIAxes_7,app.UIAxes_8,"W2",app.AnalysisWindow,app.TimeLagmsEditField.Value);
            
            %% Plot Waveforms
           
            app.Mainapp.CurrentPlotData = Spike_Waveforms_Plot_Waveforms(app.Mainapp.Data,Units,SpikeWaveforms,SpikeCluster,Waves,Wavefigs,app.Mainapp.CurrentPlotData);
            
        end

        % Value changed function: UnitstoShowEditField_2
        function UnitstoShowEditField_2ValueChanged2(app, event)
            %% Check Inputs
            
            [app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.BinNumberISIEditField.Value,app.MaxISItoshowsEditField.Value] = Spike_Module_Prepare_WaveForm_Window_and_Analysis_Check_Inputs(app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.BinNumberISIEditField.Value,app.MaxISItoshowsEditField.Value);

            [Units,Waves,Wavefigs,ISIfigs,AutoCfigs,SpikeTimes,SpikePositions,SpikeCluster,SpikeWaveforms,SpikeChannel,app.TimeLagmsEditField.Value] = Spike_Module_Prepare_WaveForm_Window_and_Analysis(app.Mainapp.Data,app.UnitstoShowEditField.Value,app.UnitstoShowEditField_2.Value,app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.UIAxes_1,app.UIAxes_2,app.UIAxes_4,app.UIAxes_5,app.UIAxes_7,app.UIAxes_8,"U2",app.AnalysisWindow,app.TimeLagmsEditField.Value);
            
            %% Plot Waveforms
           
            app.Mainapp.CurrentPlotData = Spike_Waveforms_Plot_Waveforms(app.Mainapp.Data,Units,SpikeWaveforms,SpikeCluster,Waves,Wavefigs,app.Mainapp.CurrentPlotData);

            %% Plot ISI
            
            [~,app.Mainapp.CurrentPlotData] = Spike_Module_Calculate_Plot_ISI(app.Mainapp.Data,SpikeTimes,SpikePositions,SpikeCluster,SpikeChannel,Units,Waves,ISIfigs,str2double(app.BinNumberISIEditField.Value),str2double(app.MaxISItoshowsEditField.Value),app.Mainapp.CurrentPlotData);
      
            %% Plot Autocorrelogramme

            app.Mainapp.CurrentPlotData = Spikes_Module_AutoCorrelogram(app.Mainapp.Data,SpikeTimes,SpikePositions,SpikeChannel,SpikeCluster,AutoCfigs,Units,str2double(app.BinNumberISIEditField.Value),app.Mainapp.CurrentPlotData,str2double(app.TimeLagmsEditField.Value));
            
        end

        % Value changed function: MaxISItoshowsEditField
        function MaxISItoshowsEditFieldValueChanged2(app, event)
            %% Check Inputs
            
            [app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.BinNumberISIEditField.Value,app.MaxISItoshowsEditField.Value] = Spike_Module_Prepare_WaveForm_Window_and_Analysis_Check_Inputs(app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.BinNumberISIEditField.Value,app.MaxISItoshowsEditField.Value);


            [Units,Waves,Wavefigs,ISIfigs,AutoCfigs,SpikeTimes,SpikePositions,SpikeCluster,SpikeWaveforms,SpikeChannel,app.TimeLagmsEditField.Value] = Spike_Module_Prepare_WaveForm_Window_and_Analysis(app.Mainapp.Data,app.UnitstoShowEditField.Value,app.UnitstoShowEditField_2.Value,app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.UIAxes_1,app.UIAxes_2,app.UIAxes_4,app.UIAxes_5,app.UIAxes_7,app.UIAxes_8,"BinSize",app.AnalysisWindow,app.TimeLagmsEditField.Value);

            %% Plot ISI
            
            [~,app.Mainapp.CurrentPlotData] = Spike_Module_Calculate_Plot_ISI(app.Mainapp.Data,SpikeTimes,SpikePositions,SpikeCluster,SpikeChannel,Units,Waves,ISIfigs,str2double(app.BinNumberISIEditField.Value),str2double(app.MaxISItoshowsEditField.Value),app.Mainapp.CurrentPlotData);
            
        end

        % Value changed function: BinNumberISIEditField
        function BinNumberISIEditFieldValueChanged2(app, event)
            %% Check Inputs
            
            [app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.BinNumberISIEditField.Value,app.MaxISItoshowsEditField.Value] = Spike_Module_Prepare_WaveForm_Window_and_Analysis_Check_Inputs(app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.BinNumberISIEditField.Value,app.MaxISItoshowsEditField.Value);

            [Units,Waves,Wavefigs,ISIfigs,AutoCfigs,SpikeTimes,SpikePositions,SpikeCluster,SpikeWaveforms,SpikeChannel,app.TimeLagmsEditField.Value] = Spike_Module_Prepare_WaveForm_Window_and_Analysis(app.Mainapp.Data,app.UnitstoShowEditField.Value,app.UnitstoShowEditField_2.Value,app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.UIAxes_1,app.UIAxes_2,app.UIAxes_4,app.UIAxes_5,app.UIAxes_7,app.UIAxes_8,"ISIMaxTime",app.AnalysisWindow,app.TimeLagmsEditField.Value);
            
            %% Plot ISI
            
            [~,app.Mainapp.CurrentPlotData] = Spike_Module_Calculate_Plot_ISI(app.Mainapp.Data,SpikeTimes,SpikePositions,SpikeCluster,SpikeChannel,Units,Waves,ISIfigs,str2double(app.BinNumberISIEditField.Value),str2double(app.MaxISItoshowsEditField.Value),app.Mainapp.CurrentPlotData);
      
            %% Plot Autocorrelogramme

            app.Mainapp.CurrentPlotData = Spikes_Module_AutoCorrelogram(app.Mainapp.Data,SpikeTimes,SpikePositions,SpikeChannel,SpikeCluster,AutoCfigs,Units,str2double(app.BinNumberISIEditField.Value),app.Mainapp.CurrentPlotData,str2double(app.TimeLagmsEditField.Value));
            
        end

        % Button pushed function: DeleteUnitButton
        function DeleteUnitButtonPushed2(app, event)
            cla(app.UIAxes_7)
            cla(app.UIAxes_2)
            cla(app.UIAxes_5)
            cla(app.UIAxes_8)
            cla(app.UIAxes_1)
            cla(app.UIAxes_4)

            [app.Mainapp.Data] = Utility_Delete_Units(app.Mainapp.Data,app.DeleteUnitDropDown.Value);
            
            Placeholder{1} = '';
            app.DeleteUnitDropDown.Items = Placeholder;

            TempCluster = app.Mainapp.Data.Spikes.SpikeCluster;

            if min(TempCluster)==0
                TempCluster = TempCluster + 1;
            end

            uniqueCluster = unique(TempCluster);
            
            for i = 1:length(unique(TempCluster))
                currentcluster = uniqueCluster(i);
                app.DeleteUnitDropDown.Items{i} = convertStringsToChars(strcat("Unit"," ",num2str(currentcluster)));
            end

            msgbox(strcat("Succesfully deleted ",app.DeleteUnitDropDown.Value,". Unit numbers adjusted accordingly. Plots are updated."))

            [Units,Waves,Wavefigs,ISIfigs,AutoCfigs,SpikeTimes,SpikePositions,SpikeCluster,SpikeWaveforms,SpikeChannel,app.TimeLagmsEditField.Value] = Spike_Module_Prepare_WaveForm_Window_and_Analysis(app.Mainapp.Data,app.UnitstoShowEditField.Value,app.UnitstoShowEditField_2.Value,app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.UIAxes_1,app.UIAxes_2,app.UIAxes_4,app.UIAxes_5,app.UIAxes_7,app.UIAxes_8,"StartUp",app.AnalysisWindow,app.TimeLagmsEditField.Value);

            %% Plot Waveforms
           
            app.Mainapp.CurrentPlotData = Spike_Waveforms_Plot_Waveforms(app.Mainapp.Data,Units,SpikeWaveforms,SpikeCluster,Waves,Wavefigs,app.Mainapp.CurrentPlotData);

            %% Plot ISI
            
            [~,app.Mainapp.CurrentPlotData] = Spike_Module_Calculate_Plot_ISI(app.Mainapp.Data,SpikeTimes,SpikePositions,SpikeCluster,SpikeChannel,Units,Waves,ISIfigs,str2double(app.BinNumberISIEditField.Value),str2double(app.MaxISItoshowsEditField.Value),app.Mainapp.CurrentPlotData);
      
            %% Plot Autocorrelogramme

            app.Mainapp.CurrentPlotData = Spikes_Module_AutoCorrelogram(app.Mainapp.Data,SpikeTimes,SpikePositions,SpikeChannel,SpikeCluster,AutoCfigs,Units,str2double(app.BinNumberISIEditField.Value),app.Mainapp.CurrentPlotData,str2double(app.TimeLagmsEditField.Value));
        end

        % Value changed function: TimeLagmsEditField
        function TimeLagmsEditFieldValueChanged2(app, event)
            %% Check Inputs
            
            [app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.BinNumberISIEditField.Value,app.MaxISItoshowsEditField.Value] = Spike_Module_Prepare_WaveForm_Window_and_Analysis_Check_Inputs(app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.BinNumberISIEditField.Value,app.MaxISItoshowsEditField.Value);

            %% Prepare analyiss and plots
            [Units,Waves,Wavefigs,ISIfigs,AutoCfigs,SpikeTimes,SpikePositions,SpikeCluster,SpikeWaveforms,SpikeChannel,app.TimeLagmsEditField.Value] = Spike_Module_Prepare_WaveForm_Window_and_Analysis(app.Mainapp.Data,app.UnitstoShowEditField.Value,app.UnitstoShowEditField_2.Value,app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.UIAxes_1,app.UIAxes_2,app.UIAxes_4,app.UIAxes_5,app.UIAxes_7,app.UIAxes_8,"AutoTimeLag",app.AnalysisWindow,app.TimeLagmsEditField.Value);
            
            %% Plot Autocorrelogramme

            app.Mainapp.CurrentPlotData = Spikes_Module_AutoCorrelogram(app.Mainapp.Data,SpikeTimes,SpikePositions,SpikeChannel,SpikeCluster,AutoCfigs,Units,str2double(app.BinNumberISIEditField.Value),app.Mainapp.CurrentPlotData,str2double(app.TimeLagmsEditField.Value));
            
        end

        % Menu selected function: SVGMenu
        function SVGMenuSelected(app, event)
            
        end

        % Menu selected function: xlsxMenu
        function xlsxMenuSelected(app, event)
            TimeRangePlot = size(app.Mainapp.Data.Spikes.Waveforms,2)/app.Mainapp.Data.Info.NativeSamplingRate;
            
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".xlsx",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continuous Unit(s) ",app.UnitstoShowEditField.Value," Waveform Analysis Plot ",num2str(1)),0);
        end

        % Menu selected function: xlsxMenu_2
        function xlsxMenu_2Selected(app, event)
            TimeRangePlot = size(app.Mainapp.Data.Spikes.Waveforms,2)/app.Mainapp.Data.Info.NativeSamplingRate;
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".xlsx",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continuous Unit(s) ",app.UnitstoShowEditField_2.Value," Waveform Analysis Plot ",num2str(2)),0);
        end

        % Menu selected function: xlsxMenu_3
        function xlsxMenu_3Selected(app, event)
            TimeRangePlot = size(app.Mainapp.Data.Spikes.Waveforms,2)/app.Mainapp.Data.Info.NativeSamplingRate;
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".xlsx",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continuous Unit(s) ",app.UnitstoShowEditField.Value," ISI Analysis Plot ",num2str(1)),0);
        end

        % Menu selected function: xlsxMenu_4
        function xlsxMenu_4Selected(app, event)
            TimeRangePlot = size(app.Mainapp.Data.Spikes.Waveforms,2)/app.Mainapp.Data.Info.NativeSamplingRate;
         
            StartTime = 0;
            
            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".xlsx",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continuous Unit(s) ",app.UnitstoShowEditField_2.Value," ISI Analysis Plot ",num2str(2)),0);
        end

        % Menu selected function: xlsxMenu_5
        function xlsxMenu_5Selected(app, event)
            TimeRangePlot = size(app.Mainapp.Data.Spikes.Waveforms,2)/app.Mainapp.Data.Info.NativeSamplingRate;
         
            StartTime = 0;
            
            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".xlsx",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continuous Unit(s) ",app.UnitstoShowEditField.Value," Autocorrelogram Analysis Plot ",num2str(1)),0);
        end

        % Menu selected function: xlsxMenu_6
        function xlsxMenu_6Selected(app, event)
            TimeRangePlot = size(app.Mainapp.Data.Spikes.Waveforms,2)/app.Mainapp.Data.Info.NativeSamplingRate;
         
            StartTime = 0;
            
            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".xlsx",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Continuous Unit(s) ",app.UnitstoShowEditField_2.Value," Autocorrelogram Analysis Plot ",num2str(2)),0);
        end

        % Menu selected function: LeftPlotMenu_4
        function LeftPlotMenu_4Selected(app, event)
            [file, path] = uiputfile('*.svg', 'Save as');
            
            exportgraphics(app.UIAxes_1, fullfile(path,file), 'ContentType', 'vector');
        end

        % Menu selected function: RightPlotMenu_4
        function RightPlotMenu_4Selected(app, event)
            [file, path] = uiputfile('*.svg', 'Save as');
            
            exportgraphics(app.UIAxes_2, fullfile(path,file), 'ContentType', 'vector');
        end

        % Menu selected function: LeftPlotMenu_5
        function LeftPlotMenu_5Selected(app, event)
            [file, path] = uiputfile('*.svg', 'Save as');
            
            exportgraphics(app.UIAxes_4, fullfile(path,file), 'ContentType', 'vector');
        end

        % Menu selected function: RightPlotMenu_5
        function RightPlotMenu_5Selected(app, event)
            [file, path] = uiputfile('*.svg', 'Save as');
            
            exportgraphics(app.UIAxes_5, fullfile(path,file), 'ContentType', 'vector');
        end

        % Menu selected function: LeftPlotMenu_6
        function LeftPlotMenu_6Selected(app, event)
            [file, path] = uiputfile('*.svg', 'Save as');
            
            exportgraphics(app.UIAxes_7, fullfile(path,file), 'ContentType', 'vector');
        end

        % Menu selected function: RightPlotMenu_6
        function RightPlotMenu_6Selected(app, event)
            [file, path] = uiputfile('*.svg', 'Save as');
            
            exportgraphics(app.UIAxes_8, fullfile(path,file), 'ContentType', 'vector');
        end

        % Menu selected function: LeftPlotMenu_7
        function LeftPlotMenu_7Selected(app, event)
            [file, path] = uiputfile('*.eps', 'Save as');

            exportgraphics(app.UIAxes_1, fullfile(path,file), 'ContentType', 'vector')
        end

        % Menu selected function: RightPlotMenu_7
        function RightPlotMenu_7Selected(app, event)
            [file, path] = uiputfile('*.eps', 'Save as');

            exportgraphics(app.UIAxes_2, fullfile(path,file), 'ContentType', 'vector')
        end

        % Menu selected function: LeftPlotMenu_8
        function LeftPlotMenu_8Selected(app, event)
            [file, path] = uiputfile('*.eps', 'Save as');

            exportgraphics(app.UIAxes_4, fullfile(path,file), 'ContentType', 'vector')
        end

        % Menu selected function: RightPlotMenu_8
        function RightPlotMenu_8Selected(app, event)
            [file, path] = uiputfile('*.eps', 'Save as');

            exportgraphics(app.UIAxes_5, fullfile(path,file), 'ContentType', 'vector')
        end

        % Menu selected function: LeftPlotMenu_9
        function LeftPlotMenu_9Selected(app, event)
            [file, path] = uiputfile('*.eps', 'Save as');

            exportgraphics(app.UIAxes_7, fullfile(path,file), 'ContentType', 'vector')
        end

        % Menu selected function: RightPlotMenu_9
        function RightPlotMenu_9Selected(app, event)
            [file, path] = uiputfile('*.eps', 'Save as');

            exportgraphics(app.UIAxes_8, fullfile(path,file), 'ContentType', 'vector')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UnitAnalysisWindowUIFigure = uifigure('Visible', 'off');
            app.UnitAnalysisWindowUIFigure.Name = 'Unit Analysis Window';
            app.UnitAnalysisWindowUIFigure.Icon = 'Logo.png';
            app.UnitAnalysisWindowUIFigure.CloseRequestFcn = createCallbackFcn(app, @UnitAnalysisWindowUIFigureCloseRequest, true);
            app.SnapshotMenu = uimenu(app.UnitAnalysisWindowUIFigure);
            app.SnapshotMenu.Text = '   Snapshot   ';
            app.SaveSnapshotofWindowMenu = uimenu(app.SnapshotMenu);
            app.SaveSnapshotofWindowMenu.Text = 'Save Snapshot of Window';
            app.PNGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.PNGMenu.MenuSelectedFcn = createCallbackFcn(app, @PNGMenuSelected, true);
            app.PNGMenu.Text = 'PNG';
            app.JPGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.JPGMenu.MenuSelectedFcn = createCallbackFcn(app, @JPGMenuSelected, true);
            app.JPGMenu.Text = 'JPG';
            app.SVGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.SVGMenu.MenuSelectedFcn = createCallbackFcn(app, @SVGMenuSelected, true);
            app.SVGMenu.Text = 'SVG';
            app.WaveformsMenu_2 = uimenu(app.SVGMenu);
            app.WaveformsMenu_2.Text = 'Waveforms';
            app.LeftPlotMenu_4 = uimenu(app.WaveformsMenu_2);
            app.LeftPlotMenu_4.MenuSelectedFcn = createCallbackFcn(app, @LeftPlotMenu_4Selected, true);
            app.LeftPlotMenu_4.Text = 'Left Plot';
            app.RightPlotMenu_4 = uimenu(app.WaveformsMenu_2);
            app.RightPlotMenu_4.MenuSelectedFcn = createCallbackFcn(app, @RightPlotMenu_4Selected, true);
            app.RightPlotMenu_4.Text = 'Right Plot';
            app.ISIMenu_2 = uimenu(app.SVGMenu);
            app.ISIMenu_2.Text = 'ISI';
            app.LeftPlotMenu_5 = uimenu(app.ISIMenu_2);
            app.LeftPlotMenu_5.MenuSelectedFcn = createCallbackFcn(app, @LeftPlotMenu_5Selected, true);
            app.LeftPlotMenu_5.Text = 'Left Plot';
            app.RightPlotMenu_5 = uimenu(app.ISIMenu_2);
            app.RightPlotMenu_5.MenuSelectedFcn = createCallbackFcn(app, @RightPlotMenu_5Selected, true);
            app.RightPlotMenu_5.Text = 'Right Plot';
            app.AutocorrelogramMenu_2 = uimenu(app.SVGMenu);
            app.AutocorrelogramMenu_2.Text = 'Autocorrelogram';
            app.LeftPlotMenu_6 = uimenu(app.AutocorrelogramMenu_2);
            app.LeftPlotMenu_6.MenuSelectedFcn = createCallbackFcn(app, @LeftPlotMenu_6Selected, true);
            app.LeftPlotMenu_6.Text = 'Left Plot';
            app.RightPlotMenu_6 = uimenu(app.AutocorrelogramMenu_2);
            app.RightPlotMenu_6.MenuSelectedFcn = createCallbackFcn(app, @RightPlotMenu_6Selected, true);
            app.RightPlotMenu_6.Text = 'Right Plot';
            app.EPSMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.EPSMenu.Text = 'EPS';
            app.WaveformsMenu_3 = uimenu(app.EPSMenu);
            app.WaveformsMenu_3.Text = 'Waveforms';
            app.LeftPlotMenu_7 = uimenu(app.WaveformsMenu_3);
            app.LeftPlotMenu_7.MenuSelectedFcn = createCallbackFcn(app, @LeftPlotMenu_7Selected, true);
            app.LeftPlotMenu_7.Text = 'Left Plot';
            app.RightPlotMenu_7 = uimenu(app.WaveformsMenu_3);
            app.RightPlotMenu_7.MenuSelectedFcn = createCallbackFcn(app, @RightPlotMenu_7Selected, true);
            app.RightPlotMenu_7.Text = 'Right Plot';
            app.ISIMenu_3 = uimenu(app.EPSMenu);
            app.ISIMenu_3.Text = 'ISI';
            app.LeftPlotMenu_8 = uimenu(app.ISIMenu_3);
            app.LeftPlotMenu_8.MenuSelectedFcn = createCallbackFcn(app, @LeftPlotMenu_8Selected, true);
            app.LeftPlotMenu_8.Text = 'Left Plot';
            app.RightPlotMenu_8 = uimenu(app.ISIMenu_3);
            app.RightPlotMenu_8.MenuSelectedFcn = createCallbackFcn(app, @RightPlotMenu_8Selected, true);
            app.RightPlotMenu_8.Text = 'Right Plot';
            app.AutocorrelogramMenu_3 = uimenu(app.EPSMenu);
            app.AutocorrelogramMenu_3.Text = 'Autocorrelogram';
            app.LeftPlotMenu_9 = uimenu(app.AutocorrelogramMenu_3);
            app.LeftPlotMenu_9.MenuSelectedFcn = createCallbackFcn(app, @LeftPlotMenu_9Selected, true);
            app.LeftPlotMenu_9.Text = 'Left Plot';
            app.RightPlotMenu_9 = uimenu(app.AutocorrelogramMenu_3);
            app.RightPlotMenu_9.MenuSelectedFcn = createCallbackFcn(app, @RightPlotMenu_9Selected, true);
            app.RightPlotMenu_9.Text = 'Right Plot';
            app.ExportDataMenu = uimenu(app.UnitAnalysisWindowUIFigure);
            app.ExportDataMenu.Text = '   Export Data   ';
            app.WaveformsMenu = uimenu(app.ExportDataMenu);
            app.WaveformsMenu.Text = 'Waveforms';
            app.LeftPlotMenu = uimenu(app.WaveformsMenu);
            app.LeftPlotMenu.Text = 'Left Plot';
            app.matMenu = uimenu(app.LeftPlotMenu);
            app.matMenu.MenuSelectedFcn = createCallbackFcn(app, @matMenuSelected, true);
            app.matMenu.Text = '.mat';
            app.txtMenu = uimenu(app.LeftPlotMenu);
            app.txtMenu.MenuSelectedFcn = createCallbackFcn(app, @txtMenuSelected, true);
            app.txtMenu.Text = '.txt';
            app.xlsxMenu = uimenu(app.LeftPlotMenu);
            app.xlsxMenu.MenuSelectedFcn = createCallbackFcn(app, @xlsxMenuSelected, true);
            app.xlsxMenu.Text = '.xlsx';
            app.RightPlotMenu = uimenu(app.WaveformsMenu);
            app.RightPlotMenu.Text = 'Right Plot';
            app.matMenu_2 = uimenu(app.RightPlotMenu);
            app.matMenu_2.MenuSelectedFcn = createCallbackFcn(app, @matMenu_2Selected, true);
            app.matMenu_2.Text = '.mat';
            app.txtMenu_2 = uimenu(app.RightPlotMenu);
            app.txtMenu_2.MenuSelectedFcn = createCallbackFcn(app, @txtMenu_2Selected, true);
            app.txtMenu_2.Text = '.txt';
            app.xlsxMenu_2 = uimenu(app.RightPlotMenu);
            app.xlsxMenu_2.MenuSelectedFcn = createCallbackFcn(app, @xlsxMenu_2Selected, true);
            app.xlsxMenu_2.Text = '.xlsx';
            app.ISIMenu = uimenu(app.ExportDataMenu);
            app.ISIMenu.Text = 'ISI';
            app.LeftPlotMenu_2 = uimenu(app.ISIMenu);
            app.LeftPlotMenu_2.Text = 'Left Plot';
            app.matMenu_4 = uimenu(app.LeftPlotMenu_2);
            app.matMenu_4.MenuSelectedFcn = createCallbackFcn(app, @matMenu_4Selected, true);
            app.matMenu_4.Text = '.mat';
            app.txtMenu_4 = uimenu(app.LeftPlotMenu_2);
            app.txtMenu_4.MenuSelectedFcn = createCallbackFcn(app, @txtMenu_4Selected, true);
            app.txtMenu_4.Text = '.txt';
            app.xlsxMenu_3 = uimenu(app.LeftPlotMenu_2);
            app.xlsxMenu_3.MenuSelectedFcn = createCallbackFcn(app, @xlsxMenu_3Selected, true);
            app.xlsxMenu_3.Text = '.xlsx';
            app.RightPlotMenu_2 = uimenu(app.ISIMenu);
            app.RightPlotMenu_2.Text = 'Right Plot';
            app.matMenu_5 = uimenu(app.RightPlotMenu_2);
            app.matMenu_5.MenuSelectedFcn = createCallbackFcn(app, @matMenu_5Selected, true);
            app.matMenu_5.Text = '.mat';
            app.txtMenu_5 = uimenu(app.RightPlotMenu_2);
            app.txtMenu_5.MenuSelectedFcn = createCallbackFcn(app, @txtMenu_5Selected, true);
            app.txtMenu_5.Text = '.txt';
            app.xlsxMenu_4 = uimenu(app.RightPlotMenu_2);
            app.xlsxMenu_4.MenuSelectedFcn = createCallbackFcn(app, @xlsxMenu_4Selected, true);
            app.xlsxMenu_4.Text = '.xlsx';
            app.AutocorrelogramMenu = uimenu(app.ExportDataMenu);
            app.AutocorrelogramMenu.Text = 'Autocorrelogram';
            app.LeftPlotMenu_3 = uimenu(app.AutocorrelogramMenu);
            app.LeftPlotMenu_3.Text = 'Left Plot';
            app.matMenu_7 = uimenu(app.LeftPlotMenu_3);
            app.matMenu_7.MenuSelectedFcn = createCallbackFcn(app, @matMenu_7Selected, true);
            app.matMenu_7.Text = '.mat';
            app.txtMenu_7 = uimenu(app.LeftPlotMenu_3);
            app.txtMenu_7.MenuSelectedFcn = createCallbackFcn(app, @txtMenu_7Selected, true);
            app.txtMenu_7.Text = '.txt';
            app.xlsxMenu_5 = uimenu(app.LeftPlotMenu_3);
            app.xlsxMenu_5.MenuSelectedFcn = createCallbackFcn(app, @xlsxMenu_5Selected, true);
            app.xlsxMenu_5.Text = '.xlsx';
            app.RightPlotMenu_3 = uimenu(app.AutocorrelogramMenu);
            app.RightPlotMenu_3.Text = 'Right Plot';
            app.matMenu_8 = uimenu(app.RightPlotMenu_3);
            app.matMenu_8.MenuSelectedFcn = createCallbackFcn(app, @matMenu_8Selected, true);
            app.matMenu_8.Text = '.mat';
            app.txtMenu_8 = uimenu(app.RightPlotMenu_3);
            app.txtMenu_8.MenuSelectedFcn = createCallbackFcn(app, @txtMenu_8Selected, true);
            app.txtMenu_8.Text = '.txt';
            app.xlsxMenu_6 = uimenu(app.RightPlotMenu_3);
            app.xlsxMenu_6.MenuSelectedFcn = createCallbackFcn(app, @xlsxMenu_6Selected, true);
            app.xlsxMenu_6.Text = '.xlsx';
            app.UIAxes_4 = uiaxes(app.UnitAnalysisWindowUIFigure);
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_1 = uiaxes(app.UnitAnalysisWindowUIFigure);
            zlabel(app.UIAxes_1, 'Z')
            app.UIAxes_8 = uiaxes(app.UnitAnalysisWindowUIFigure);
            zlabel(app.UIAxes_8, 'Z')
            app.UIAxes_5 = uiaxes(app.UnitAnalysisWindowUIFigure);
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_2 = uiaxes(app.UnitAnalysisWindowUIFigure);
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_7 = uiaxes(app.UnitAnalysisWindowUIFigure);
            zlabel(app.UIAxes_7, 'Z')
            app.PlotColumnSettingsPanel = uipanel(app.UnitAnalysisWindowUIFigure);
            app.PlotColumnSettingsPanel.Title = 'Plot Column Settings';
            app.NumWaveformsEditFieldLabel = uilabel(app.PlotColumnSettingsPanel);
            app.NumWaveformsEditFieldLabel.Text = 'Num Waveforms';
            app.NumWaveformsEditField = uieditfield(app.PlotColumnSettingsPanel, 'text');
            app.NumWaveformsEditField.ValueChangedFcn = createCallbackFcn(app, @NumWaveformsEditFieldValueChanged2, true);
            app.UnittoShowEditFieldLabel = uilabel(app.PlotColumnSettingsPanel);
            app.UnittoShowEditFieldLabel.Text = 'Unit to Show';
            app.UnitstoShowEditField = uieditfield(app.PlotColumnSettingsPanel, 'text');
            app.UnitstoShowEditField.ValueChangedFcn = createCallbackFcn(app, @UnitstoShowEditFieldValueChanged2, true);
            app.UnittoShowEditFieldLabel_2 = uilabel(app.PlotColumnSettingsPanel);
            app.UnittoShowEditFieldLabel_2.Text = 'Unit to Show';
            app.UnitstoShowEditField_2 = uieditfield(app.PlotColumnSettingsPanel, 'text');
            app.UnitstoShowEditField_2.ValueChangedFcn = createCallbackFcn(app, @UnitstoShowEditField_2ValueChanged2, true);
            app.NumWaveformsEditField_2Label = uilabel(app.PlotColumnSettingsPanel);
            app.NumWaveformsEditField_2Label.Text = 'Num Waveforms';
            app.NumWaveformsEditField_2 = uieditfield(app.PlotColumnSettingsPanel, 'text');
            app.NumWaveformsEditField_2.ValueChangedFcn = createCallbackFcn(app, @NumWaveformsEditField_2ValueChanged2, true);
            app.GeneralSettingsPanel = uipanel(app.UnitAnalysisWindowUIFigure);
            app.GeneralSettingsPanel.Title = 'General Settings';
            app.BinNumberISILabel = uilabel(app.GeneralSettingsPanel);
            app.BinNumberISILabel.Text = 'Bin Number';
            app.BinNumberISIEditField = uieditfield(app.GeneralSettingsPanel, 'text');
            app.BinNumberISIEditField.ValueChangedFcn = createCallbackFcn(app, @BinNumberISIEditFieldValueChanged2, true);
            app.BinNumberISIEditField.Value = '100';
            app.DeleteUnitDropDownLabel = uilabel(app.GeneralSettingsPanel);
            app.DeleteUnitDropDownLabel.Text = 'Delete Unit';
            app.DeleteUnitDropDown = uidropdown(app.GeneralSettingsPanel);
            app.DeleteUnitDropDown.Items = {''};
            app.DeleteUnitDropDown.Value = '';
            app.TimeLagmsEditFieldLabel = uilabel(app.GeneralSettingsPanel);
            app.TimeLagmsEditFieldLabel.Text = 'Time Lag [ms]';
            app.TimeLagmsEditField = uieditfield(app.GeneralSettingsPanel, 'text');
            app.TimeLagmsEditField.ValueChangedFcn = createCallbackFcn(app, @TimeLagmsEditFieldValueChanged2, true);
            app.TimeLagmsEditField.Value = '20';
            app.DeleteUnitButton = uibutton(app.GeneralSettingsPanel, 'push');
            app.DeleteUnitButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteUnitButtonPushed2, true);
            app.DeleteUnitButton.Text = 'Delete Unit';
            app.MaxISItoshowsLabel = uilabel(app.GeneralSettingsPanel);
            app.MaxISItoshowsLabel.Text = 'Max ISI to show [s]';
            app.MaxISItoshowsEditField = uieditfield(app.GeneralSettingsPanel, 'text');
            app.MaxISItoshowsEditField.ValueChangedFcn = createCallbackFcn(app, @MaxISItoshowsEditFieldValueChanged2, true);
            app.MaxISItoshowsEditField.Value = '0.150';
            app.GeneralSettingsLabel = uilabel(app.GeneralSettingsPanel);
            app.GeneralSettingsLabel.Text = 'General Settings';
            app.PlotColumnSettingsLabel = uilabel(app.UnitAnalysisWindowUIFigure);
            app.PlotColumnSettingsLabel.Text = 'Plot Column Settings';
            app.CloseButton = uibutton(app.UnitAnalysisWindowUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed2, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.UnitAnalysisWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Continuous_Unit_Analysis_Window(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UnitAnalysisWindowUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UnitAnalysisWindowUIFigure)
        end
    end
end