classdef Analyse_Event_Related_Signal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Analyse_Event_Related_SignalUIFigure  matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        RightPanel                   matlab.ui.container.Panel
        SelectEventRelatedAnalysisLabel  matlab.ui.control.Label
        CloseButton                  matlab.ui.control.Button
        SelectEventRelatedAnalysisPanel  matlab.ui.container.Panel
        FieldTripLFPAnalysisButton   matlab.ui.control.Button
        PhaseSynchronizationButton   matlab.ui.control.Button
        EventRelatedPotentialButton  matlab.ui.control.Button
        StaticPowerSpectrumButton    matlab.ui.control.Button
        TimeFrequencyPowerSpectrumButton  matlab.ui.control.Button
        CurrentSourceDensityButton   matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Mainapp = []; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            % Moce this window in the middle of thescreen
            movegui(app.Analyse_Event_Related_SignalUIFigure, 'center');
            
            app.Mainapp = Mainapp;

            app = Utility_Change_Light_Dark_Mode(app,"EventLFPMain");

        end

        % Button pushed function: EventRelatedPotentialButton
        function EventRelatedPotentialButtonPushed(app, event)
            if isempty(app.Mainapp.ProbeViewWindowHandle) || ~isprop(app.Mainapp.ProbeViewWindowHandle,'ProbeViewUIFigure')
                app.Mainapp.ProbeViewWindowHandle = Probe_View_Window(app.Mainapp,'MainWindow');
            end

            if ~isempty(app.Mainapp.ProbeViewWindowHandle) && isprop(app.Mainapp.ProbeViewWindowHandle,'ProbeViewUIFigure') % Add option to probe view when available
                AlreadyIn = 0;
                for i = 1:length(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                    if strcmp(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Event ERP Window')
                        AlreadyIn = 1;
                    end
                end
                if AlreadyIn == 0
                    app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{end+1} = 'Event ERP Window';
                end
            else % if no probe view available
 
            end

            app.Mainapp.EventLFPERP = ERP_Analysis_Window(app,app.Mainapp);

            [~] = Utility_Set_ToolTips(app.Mainapp,app.Mainapp.ShowToolTipsSetting,"EventERP");
        end

        % Button pushed function: CurrentSourceDensityButton
        function CurrentSourceDensityButtonPushed(app, event)
            if isempty(app.Mainapp.ProbeViewWindowHandle) || ~isprop(app.Mainapp.ProbeViewWindowHandle,'ProbeViewUIFigure')
                app.Mainapp.ProbeViewWindowHandle = Probe_View_Window(app.Mainapp,'MainWindow');
            end

            if ~isempty(app.Mainapp.ProbeViewWindowHandle) && isprop(app.Mainapp.ProbeViewWindowHandle,'ProbeViewUIFigure') % Add option to probe view when available
                AlreadyIn = 0;
                for i = 1:length(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                    if strcmp(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Event CSD Window')
                        AlreadyIn = 1;
                    end
                end
                if AlreadyIn == 0
                    app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{end+1} = 'Event CSD Window';
                end
            else % if no probe view available
 
            end

            app.Mainapp.EventLFPCSD = CSD_Analysis_Window(app,app.Mainapp);

            [~] = Utility_Set_ToolTips(app.Mainapp,app.Mainapp.ShowToolTipsSetting,"EventCSD");
        end

        % Button pushed function: TimeFrequencyPowerSpectrumButton
        function TimeFrequencyPowerSpectrumButtonPushed(app, event)
            if isempty(app.Mainapp.ProbeViewWindowHandle) || ~isprop(app.Mainapp.ProbeViewWindowHandle,'ProbeViewUIFigure')
                app.Mainapp.ProbeViewWindowHandle = Probe_View_Window(app.Mainapp,'MainWindow');
            end

            if ~isempty(app.Mainapp.ProbeViewWindowHandle) && isprop(app.Mainapp.ProbeViewWindowHandle,'ProbeViewUIFigure') % Add option to probe view when available
                AlreadyIn = 0;
                for i = 1:length(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                    if strcmp(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Event Time Frequency Power Window')
                        AlreadyIn = 1;
                    end
                end
                if AlreadyIn == 0
                    app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{end+1} = 'Event Time Frequency Power Window';
                end
            else % if no probe view available
 
            end

            app.Mainapp.EventLFPTF = Time_Frequency_Power_Window_Spectrogram(app,app.Mainapp);
            [~] = Utility_Set_ToolTips(app.Mainapp,app.Mainapp.ShowToolTipsSetting,"EventTimeFrequencyPower");

        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.Mainapp.LFPEventsMainWindow = [];
            delete(app);
        end

        % Button pushed function: StaticPowerSpectrumButton
        function StaticPowerSpectrumButtonPushed(app, event)
            if isempty(app.Mainapp.ProbeViewWindowHandle) || ~isprop(app.Mainapp.ProbeViewWindowHandle,'ProbeViewUIFigure')
                app.Mainapp.ProbeViewWindowHandle = Probe_View_Window(app.Mainapp,'MainWindow');
            end

            if ~isempty(app.Mainapp.ProbeViewWindowHandle) && isprop(app.Mainapp.ProbeViewWindowHandle,'ProbeViewUIFigure') % Add option to probe view when available
                AlreadyIn = 0;
                for i = 1:length(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                    if strcmp(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Event Static Spectrum Window')
                        AlreadyIn = 1;
                    end
                end
                if AlreadyIn == 0
                    app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{end+1} = 'Event Static Spectrum Window';
                end
            else % if no probe view available
 
            end

            app.Mainapp.EventLFPSSP = Event_Static_Power_Spectrum_Window(app.Mainapp);
            
            [~] = Utility_Set_ToolTips(app.Mainapp,app.Mainapp.ShowToolTipsSetting,"EventStaticSpectrum");
            
        end

        % Close request function: Analyse_Event_Related_SignalUIFigure
        function Analyse_Event_Related_SignalUIFigureCloseRequest(app, event)
            app.Mainapp.LFPEventsMainWindow = [];
            delete(app);
        end

        % Button pushed function: PhaseSynchronizationButton
        function PhaseSynchronizationButtonPushed(app, event)
            if isempty(app.Mainapp.ProbeViewWindowHandle) || ~isprop(app.Mainapp.ProbeViewWindowHandle,'ProbeViewUIFigure')
                app.Mainapp.ProbeViewWindowHandle = Probe_View_Window(app.Mainapp,'MainWindow');
            end

            if ~isempty(app.Mainapp.ProbeViewWindowHandle) && isprop(app.Mainapp.ProbeViewWindowHandle,'ProbeViewUIFigure') % Add option to probe view when available
                AlreadyIn = 0;
                for i = 1:length(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                    if strcmp(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Event Phase Synchronization')
                        AlreadyIn = 1;
                    end
                end
                if AlreadyIn == 0
                    app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{end+1} = 'Event Phase Synchronization';
                end
            else % if no probe view available
 
            end

            app.Mainapp.EventPhaseSynchro = Event_PhaseSynchro_Analysis_Window(app,app.Mainapp);
            [~] = Utility_Set_ToolTips(app.Mainapp,app.Mainapp.ShowToolTipsSetting,"EventECHT");
        end

        % Button pushed function: FieldTripLFPAnalysisButton
        function FieldTripLFPAnalysisButtonPushed(app, event)
            if ~isfield(app.Mainapp.Data.Info,'EventChannelNames')
                msgbox("Error: No event related information found. Please first extract events to set event related information");
            else
                app.Mainapp.FieldTripLFPWindow = FieldTrip_Event_Analysis_Window(app.Mainapp);
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.Analyse_Event_Related_SignalUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {222, 222};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Analyse_Event_Related_SignalUIFigure = uifigure('Visible', 'off');
            app.Analyse_Event_Related_SignalUIFigure.Name = 'Analyse_Event_Related_Signal';
            app.Analyse_Event_Related_SignalUIFigure.Icon = 'Logo.png';
            app.Analyse_Event_Related_SignalUIFigure.CloseRequestFcn = createCallbackFcn(app, @Analyse_Event_Related_SignalUIFigureCloseRequest, true);
            app.Analyse_Event_Related_SignalUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.Analyse_Event_Related_SignalUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.SelectEventRelatedAnalysisPanel = uipanel(app.RightPanel);
            app.SelectEventRelatedAnalysisPanel.Title = 'Select Event Related Analysis';
            app.CurrentSourceDensityButton = uibutton(app.SelectEventRelatedAnalysisPanel, 'push');
            app.CurrentSourceDensityButton.ButtonPushedFcn = createCallbackFcn(app, @CurrentSourceDensityButtonPushed, true);
            app.CurrentSourceDensityButton.Text = {'Current Source'; 'Density'};
            app.TimeFrequencyPowerSpectrumButton = uibutton(app.SelectEventRelatedAnalysisPanel, 'push');
            app.TimeFrequencyPowerSpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @TimeFrequencyPowerSpectrumButtonPushed, true);
            app.TimeFrequencyPowerSpectrumButton.Text = {'Time-Frequency'; 'Power Spectrum'};
            app.StaticPowerSpectrumButton = uibutton(app.SelectEventRelatedAnalysisPanel, 'push');
            app.StaticPowerSpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @StaticPowerSpectrumButtonPushed, true);
            app.StaticPowerSpectrumButton.Text = {'Static Power '; 'Spectrum'};
            app.EventRelatedPotentialButton = uibutton(app.SelectEventRelatedAnalysisPanel, 'push');
            app.EventRelatedPotentialButton.ButtonPushedFcn = createCallbackFcn(app, @EventRelatedPotentialButtonPushed, true);
            app.EventRelatedPotentialButton.Text = {'Event Related'; 'Potential'};
            app.PhaseSynchronizationButton = uibutton(app.SelectEventRelatedAnalysisPanel, 'push');
            app.PhaseSynchronizationButton.ButtonPushedFcn = createCallbackFcn(app, @PhaseSynchronizationButtonPushed, true);
            app.PhaseSynchronizationButton.Text = {'Phase'; 'Synchronization'};
            app.FieldTripLFPAnalysisButton = uibutton(app.SelectEventRelatedAnalysisPanel, 'push');
            app.FieldTripLFPAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @FieldTripLFPAnalysisButtonPushed, true);
            app.FieldTripLFPAnalysisButton.Text = {'FieldTrip LFP'; 'Analysis'};
            app.CloseButton = uibutton(app.RightPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.SelectEventRelatedAnalysisLabel = uilabel(app.RightPanel);
            app.SelectEventRelatedAnalysisLabel.Text = 'Select Event Related Analysis';
            % Show the figure after all components are created
            app.Analyse_Event_Related_SignalUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Analyse_Event_Related_Signal(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.Analyse_Event_Related_SignalUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.Analyse_Event_Related_SignalUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Analyse_Event_Related_SignalUIFigure)
        end
    end
end