classdef Event_PhaseSynchro_Analysis_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PhaseSynchroFigure              matlab.ui.Figure
        SnapshotMenu                    matlab.ui.container.Menu
        SaveSnapshotofWindowMenu        matlab.ui.container.Menu
        PNGMenu                         matlab.ui.container.Menu
        JPGMenu                         matlab.ui.container.Menu
        SVGMenu                         matlab.ui.container.Menu
        TopPlotMenu                     matlab.ui.container.Menu
        MiddlePlotMenu                  matlab.ui.container.Menu
        BottomLeftPlotMenu              matlab.ui.container.Menu
        BottomRightPlotMenu             matlab.ui.container.Menu
        EPSMenu                         matlab.ui.container.Menu
        TopPlotMenu_2                   matlab.ui.container.Menu
        MiddlePlotMenu_2                matlab.ui.container.Menu
        BottomLeftPlotMenu_2            matlab.ui.container.Menu
        BottomRightPlotMenu_2           matlab.ui.container.Menu
        PlotMenu                        matlab.ui.container.Menu
        ChangePlotPropertiesMenu        matlab.ui.container.Menu
        SingleERPPlotMenu               matlab.ui.container.Menu
        FontSizeMenu                    matlab.ui.container.Menu
        LineWidthEventsMenu_3           matlab.ui.container.Menu
        LineWidthMeanMenu               matlab.ui.container.Menu
        LineWidthTriggerMenu            matlab.ui.container.Menu
        ColorEventsMenu_3               matlab.ui.container.Menu
        ColorMeanMenu                   matlab.ui.container.Menu
        ColorTriggerMenu                matlab.ui.container.Menu
        BackgorundColorMenu             matlab.ui.container.Menu
        XLabelMenu                      matlab.ui.container.Menu
        YLabelMenu                      matlab.ui.container.Menu
        RESETMenu                       matlab.ui.container.Menu
        MultipleERPPlotMenu_2           matlab.ui.container.Menu
        FontSizeMenu_3                  matlab.ui.container.Menu
        LineWidthMeanMenu_2             matlab.ui.container.Menu
        LineWidthTriggerMenu_2          matlab.ui.container.Menu
        ColorTriggerMenu_2              matlab.ui.container.Menu
        BackgorundColorMenu_2           matlab.ui.container.Menu
        XLabelMenu_3                    matlab.ui.container.Menu
        YLabelMenu_3                    matlab.ui.container.Menu
        RESETMenu_3                     matlab.ui.container.Menu
        SaveCustomeAppearanceMenu       matlab.ui.container.Menu
        DeleteCustomeAppearanceMenu     matlab.ui.container.Menu
        ExportDataMenu                  matlab.ui.container.Menu
        ERPoverEventsMenu               matlab.ui.container.Menu
        matMenu                         matlab.ui.container.Menu
        txtMenu                         matlab.ui.container.Menu
        ERPoverChannelMenu              matlab.ui.container.Menu
        matMenu_2                       matlab.ui.container.Menu
        txtMenu_2                       matlab.ui.container.Menu
        ExtrasMenu                      matlab.ui.container.Menu
        ChangeLowPassParameterMenu      matlab.ui.container.Menu
        ShowDataAnalysedMenu            matlab.ui.container.Menu
        ONMenu                          matlab.ui.container.Menu
        OFFMenu                         matlab.ui.container.Menu
        ChangeFilterTypeMenu            matlab.ui.container.Menu
        ButterworthMenu                 matlab.ui.container.Menu
        FIRMenu                         matlab.ui.container.Menu
        HowtoUseEventRelatedDataMenu    matlab.ui.container.Menu
        ComputeResultsforERPmeanovertrialsMenu  matlab.ui.container.Menu
        ComputeResultsforSupertrialalltrialsconcatonatedMenu  matlab.ui.container.Menu
        ChannelSelectionDropDown_2      matlab.ui.control.DropDown
        ChannelSelectionDropDown_2Label  matlab.ui.control.Label
        vsLabel                         matlab.ui.control.Label
        ChannelSelectionDropDown        matlab.ui.control.DropDown
        TextArea                        matlab.ui.control.TextArea
        Panel                           matlab.ui.container.Panel
        CloseButton                     matlab.ui.control.Button
        SettingsPanel                   matlab.ui.container.Panel
        EventNumberSelectionEditField_2  matlab.ui.control.EditField
        EventNumberSelectionEditField   matlab.ui.control.EditField
        TriggerNumberSelectionMatlabExpressionsLabel  matlab.ui.control.Label
        BaselineNormalizeCheckBox       matlab.ui.control.CheckBox
        SettingsLabel                   matlab.ui.control.Label
        CalculationMethodDropDown       matlab.ui.control.DropDown
        CalculationMethodDropDownLabel  matlab.ui.control.Label
        TrialSelectionMatlabExpressionsEditField  matlab.ui.control.EditField
        TrialSelectionMatlabExpressionsEditFieldLabel  matlab.ui.control.Label
        DataTypeDropDown                matlab.ui.control.DropDown
        EventDataTypeDropDownLabel      matlab.ui.control.Label
        EventChannelSelectionDropDown   matlab.ui.control.DropDown
        EventChannelSelectionDropDownLabel  matlab.ui.control.Label
        DataToExtractFromDropDown       matlab.ui.control.DropDown
        DataToExtractFromDropDownLabel  matlab.ui.control.Label
        ECHTFilterorderLabel            matlab.ui.control.Label
        ECHTFilterorderEditField        matlab.ui.control.EditField
        ForceFilterOFFCheckBox          matlab.ui.control.CheckBox
        NarrowbandFilterorderEditField  matlab.ui.control.EditField
        NarrowbandFilterorderEditFieldLabel  matlab.ui.control.Label
        NarrowbandCutoffLowerHigherEditField  matlab.ui.control.EditField
        NarrowbandCutoffLowerHigherEditFieldLabel  matlab.ui.control.Label
        MultipleTimeRangesComparisonPanel  matlab.ui.container.Panel
        MultipleTimeRangesComparisonLabel  matlab.ui.control.Label
        TimeRange2fromtoinsEditField    matlab.ui.control.EditField
        TimeRange2fromtoinsEditFieldLabel  matlab.ui.control.Label
        TimeRange1fromtoinsEditField    matlab.ui.control.EditField
        TimeRange1fromtoinsEditFieldLabel  matlab.ui.control.Label
        CompareTimeRangesCheckBox       matlab.ui.control.CheckBox
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        ContextMenu4                    matlab.ui.container.ContextMenu
        ChangeSpacingLimitsMenu         matlab.ui.container.Menu
    end

    
    properties (Access = public)
        EventRelatedWindow = []; % Description
        EventTime = [];
        TimearoundEvent = [];
        colorMap = [];
        Mainapp = [];
        ChannelToCompare = [];
        PolarPlot = [];
        Ccolormap = [];
        WhatToDo = [];
        BasedOnERP = 1;
        ShowAnayzedData = 0;
        EventsToShow = [];

        ChangeFilterSettingsWindow = [];
        FilterType = 'FIR';
        LowPassSettings = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, EventRelatedWindow, Mainapp)
            movegui(app.PhaseSynchroFigure, 'center');

            app.Mainapp = Mainapp;
            app.EventRelatedWindow = EventRelatedWindow;

            app = Utility_Change_Light_Dark_Mode(app,"EventPhaseSyncWindow");

            if ~isfield(app.Mainapp.Data,'Events')
                msgbox("No events extracted. Please extract events first!. Exiting.");
                delete(app);
                return;
            end

            % set up filter options
            app.ButterworthMenu.ForegroundColor = [1,0,0];
            app.FIRMenu.ForegroundColor = [0,1,0];
            
            app.WhatToDo = 'All';

            % standard settings
            app.LowPassSettings.Cutoff = 300;
            app.LowPassSettings.FilterOrder = 4;

            %% --------------------- Setup Fields ---------------------
            if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                app.DataTypeDropDown.Items{1} = 'Raw Event Related Data';
                app.DataTypeDropDown.Items{2} = 'Preprocessed Event Related Data';
            else
                app.DataTypeDropDown.Items{1} = 'Raw Event Related Data';
            end

            if isfield(app.Mainapp.Data,'Preprocessed')
                app.DataToExtractFromDropDown.Items{1} = 'Raw Data';
                app.DataToExtractFromDropDown.Items{2} = 'Preprocessed Data';
            else
                app.DataToExtractFromDropDown.Items{1} = 'Raw Data';
            end
            
            for i = 1:length(app.Mainapp.Data.Info.EventChannelNames)
                app.EventChannelSelectionDropDown.Items{i} = convertStringsToChars(app.Mainapp.Data.Info.EventChannelNames{i});
            end

            spaceindicie = strfind(app.Mainapp.Data.Info.EventRelatedDataTimeRange," ");
            app.TimearoundEvent(1) = str2double(app.Mainapp.Data.Info.EventRelatedDataTimeRange(1:spaceindicie(1)-1));
            app.TimearoundEvent(2) = str2double(app.Mainapp.Data.Info.EventRelatedDataTimeRange(spaceindicie(1)+1:end));

            %% -------------------- Check if prepro event related data found and can be offered -------------------- 
            [texttoshow,Value] = Event_Module_Check_Event_Preprocessing(app.Mainapp.Data,app.EventChannelSelectionDropDown.Value,"Raw Event Related Data");
            app.DataTypeDropDown.Items = {};
            app.DataTypeDropDown.Items = texttoshow;
            app.DataTypeDropDown.Value = Value;

            %% -------------------- Extract Event Related Data -------------------- 
            [app.Mainapp.Data,~] = Event_Module_Extract_Event_Related_Data(app.Mainapp.Data,app.EventChannelSelectionDropDown.Value,app.TimearoundEvent,app.DataToExtractFromDropDown.Value,app.DataTypeDropDown.Value);
            
            %% -------------------- Set Basline Normlaization and trigger nhumber -------------------- 
            EventRange = str2double(strsplit(app.Mainapp.Data.Info.EventRelatedDataTimeRange,' '));
            app.EventNumberSelectionEditField.Value = strcat('-',num2str(EventRange(1)),',0');
            
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                app.EventNumberSelectionEditField_2.Value = strcat(num2str(size(app.Mainapp.Data.EventRelatedData,2)),' Trigger');
            else
                app.EventNumberSelectionEditField_2.Value = strcat(num2str(size(app.Mainapp.Data.PreprocessedEventRelatedData,2)),' Trigger');
            end

            %% -------------------- Populate event channel selection -------------------- 
            [app.TrialSelectionMatlabExpressionsEditField.Value] = Event_Module_Check_EventInput(app.TrialSelectionMatlabExpressionsEditField.Value,app.Mainapp.Data,app.EventChannelSelectionDropDown.Value,app.DataTypeDropDown.Value,1);
            app.EventsToShow = eval(app.TrialSelectionMatlabExpressionsEditField.Value);
            
            %% --------------------- Set Up Text Area Information ---------------------
            %app.InformationTextArea.Value = Utility_Show_Narrowband_FilterStatusMessage(app.Mainapp.Data,app.InformationTextArea.Value,app.Mainapp.DropDown.Value);
            % automatically find filter order and populate filter order
            % text area
            freqs = str2double(strsplit(app.NarrowbandCutoffLowerHigherEditField.Value,','));
            
            [app.NarrowbandFilterorderEditField.Value] = Utility_Determine_FIR_FilterOrder(app,freqs(1));

            % Set up polar plot
            app.PolarPlot = polaraxes('Parent', app.Panel);
            app.Ccolormap = parula(size(app.Mainapp.Data.Raw,1));

            %% --------------------- Setup Up Channel ---------------------
            AllChannels = {};
            if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 1
                TempActiveChannel = (str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)*str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)+1)-sort(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
                for i = 1:length(TempActiveChannel)
                    AllChannels{i} = num2str(TempActiveChannel(i));
                end
            else
                for i = 1:length(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel)
                    AllChannels{i} = num2str(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel(i));
                end
            end

            app.ChannelSelectionDropDown.Items = AllChannels;
            app.ChannelSelectionDropDown_2.Items = AllChannels;
            app.ChannelSelectionDropDown_2.Value = app.ChannelSelectionDropDown_2.Items{2};
            
            app.ECHTFilterorderEditField.Enable = "off";

            app.ChannelToCompare = [str2double(app.ChannelSelectionDropDown.Value),str2double(app.ChannelSelectionDropDown_2.Value)];

            %% --------------------- Compute and Plot ---------------------
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                DataToCompute = app.Mainapp.Data.EventRelatedData(:,app.EventsToShow,:);
            else
                DataToCompute = app.Mainapp.Data.PreprocessedEventRelatedData(:,app.EventsToShow,:);
            end
            
            [app.TextArea.Value,app.Mainapp.CurrentPlotData] = Event_Module_PhaseSync_Main(DataToCompute,app.Mainapp.Data.Info.EventRelatedTime,app.PolarPlot,app.UIAxes_3,app.UIAxes,app.UIAxes_2,app.Mainapp.Data,app.ChannelToCompare,app.NarrowbandCutoffLowerHigherEditField.Value,app.NarrowbandFilterorderEditField.Value,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.Mainapp.PlotAppearance,app.Ccolormap,app.CalculationMethodDropDown.Value,app.ForceFilterOFFCheckBox.Value,app.ECHTFilterorderEditField.Value,app.Mainapp.CurrentPlotData,app.WhatToDo,app.BasedOnERP,app.ShowAnayzedData,app.DataToExtractFromDropDown.Value,app.LowPassSettings,app.FilterType,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
            
        end

        % Callback function
        function CloseButtonPushed(app, event)
            app.Mainapp.EventLFPERP = [];

            if ~isempty(app.Mainapp.ProbeViewWindowHandle) % delete option from probe view 
                for i = 1:length(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                    if strcmp(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Event ERP Window')
                        app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items(i) = [];
                        break;
                    end
                end
            else % if no probe view available

            end

            delete(app)
        end

        % Menu selected function: PNGMenu
        function PNGMenuSelected(app, event)
            [file, path] = uiputfile('*.png', 'Save as');

            exportapp(app.PhaseSynchroFigure,fullfile(path,file)) 
        end

        % Menu selected function: JPGMenu
        function JPGMenuSelected(app, event)
            [file, path] = uiputfile('*.jpg', 'Save as');

            exportapp(app.PhaseSynchroFigure,fullfile(path,file)) 
        end

        % Menu selected function: matMenu
        function matMenuSelected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat", app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Event Related Potential over Events",0);
        end

        % Menu selected function: txtMenu
        function txtMenuSelected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt", app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Event Related Potential over Events",0);
        end

        % Menu selected function: matMenu_2
        function matMenu_2Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat", app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Event Related Potential over Channel",0);
        end

        % Menu selected function: txtMenu_2
        function txtMenu_2Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt", app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Event Related Potential over Channel",0);
        end

        % Menu selected function: ChangeSpacingLimitsMenu
        function ChangeSpacingLimitsMenuSelected(app, event)
         
        end

        % Menu selected function: FontSizeMenu
        function FontSizeMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'fontsize', app.Mainapp,"SingleERPWindowDataFontSize");
            waitfor(h);

            %% --------------------- Compute and Plot ---------------------
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                DataToCompute = app.Mainapp.Data.EventRelatedData(:,app.EventsToShow,:);
            else
                DataToCompute = app.Mainapp.Data.PreprocessedEventRelatedData(:,app.EventsToShow,:);
            end

            [app.TextArea.Value,app.Mainapp.CurrentPlotData] = Event_Module_PhaseSync_Main(DataToCompute,app.Mainapp.Data.Info.EventRelatedTime,app.PolarPlot,app.UIAxes_3,app.UIAxes,app.UIAxes_2,app.Mainapp.Data,app.ChannelToCompare,app.NarrowbandCutoffLowerHigherEditField.Value,app.NarrowbandFilterorderEditField.Value,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.Mainapp.PlotAppearance,app.Ccolormap,app.CalculationMethodDropDown.Value,app.ForceFilterOFFCheckBox.Value,app.ECHTFilterorderEditField.Value,app.Mainapp.CurrentPlotData,app.WhatToDo,app.BasedOnERP,app.ShowAnayzedData,app.DataToExtractFromDropDown.Value,app.LowPassSettings,app.FilterType,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
            
        end

        % Menu selected function: XLabelMenu
        function XLabelMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'xlabel', app.Mainapp,"SingleERPWindowXLabel");
            waitfor(h);

            %% --------------------- Compute and Plot ---------------------
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                DataToCompute = app.Mainapp.Data.EventRelatedData(:,app.EventsToShow,:);
            else
                DataToCompute = app.Mainapp.Data.PreprocessedEventRelatedData(:,app.EventsToShow,:);
            end

            [app.TextArea.Value,app.Mainapp.CurrentPlotData] = Event_Module_PhaseSync_Main(DataToCompute,app.Mainapp.Data.Info.EventRelatedTime,app.PolarPlot,app.UIAxes_3,app.UIAxes,app.UIAxes_2,app.Mainapp.Data,app.ChannelToCompare,app.NarrowbandCutoffLowerHigherEditField.Value,app.NarrowbandFilterorderEditField.Value,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.Mainapp.PlotAppearance,app.Ccolormap,app.CalculationMethodDropDown.Value,app.ForceFilterOFFCheckBox.Value,app.ECHTFilterorderEditField.Value,app.Mainapp.CurrentPlotData,app.WhatToDo,app.BasedOnERP,app.ShowAnayzedData,app.DataToExtractFromDropDown.Value,app.LowPassSettings,app.FilterType,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
            
        end

        % Menu selected function: YLabelMenu
        function YLabelMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'ylabel', app.Mainapp,"SingleERPWindowYLabel");
            waitfor(h);
  
            %% --------------------- Compute and Plot ---------------------
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                DataToCompute = app.Mainapp.Data.EventRelatedData(:,app.EventsToShow,:);
            else
                DataToCompute = app.Mainapp.Data.PreprocessedEventRelatedData(:,app.EventsToShow,:);
            end

            [app.TextArea.Value,app.Mainapp.CurrentPlotData] = Event_Module_PhaseSync_Main(DataToCompute,app.Mainapp.Data.Info.EventRelatedTime,app.PolarPlot,app.UIAxes_3,app.UIAxes,app.UIAxes_2,app.Mainapp.Data,app.ChannelToCompare,app.NarrowbandCutoffLowerHigherEditField.Value,app.NarrowbandFilterorderEditField.Value,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.Mainapp.PlotAppearance,app.Ccolormap,app.CalculationMethodDropDown.Value,app.ForceFilterOFFCheckBox.Value,app.ECHTFilterorderEditField.Value,app.Mainapp.CurrentPlotData,app.WhatToDo,app.BasedOnERP,app.ShowAnayzedData,app.DataToExtractFromDropDown.Value,app.LowPassSettings,app.FilterType,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
            
        end

        % Menu selected function: FontSizeMenu_3
        function FontSizeMenu_3Selected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes_2,'fontsize', app.Mainapp,"MultipleERPWindowDataFontSize");
            waitfor(h);

            %% --------------------- Compute and Plot ---------------------
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                DataToCompute = app.Mainapp.Data.EventRelatedData(:,app.EventsToShow,:);
            else
                DataToCompute = app.Mainapp.Data.PreprocessedEventRelatedData(:,app.EventsToShow,:);
            end

            [app.TextArea.Value,app.Mainapp.CurrentPlotData] = Event_Module_PhaseSync_Main(DataToCompute,app.Mainapp.Data.Info.EventRelatedTime,app.PolarPlot,app.UIAxes_3,app.UIAxes,app.UIAxes_2,app.Mainapp.Data,app.ChannelToCompare,app.NarrowbandCutoffLowerHigherEditField.Value,app.NarrowbandFilterorderEditField.Value,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.Mainapp.PlotAppearance,app.Ccolormap,app.CalculationMethodDropDown.Value,app.ForceFilterOFFCheckBox.Value,app.ECHTFilterorderEditField.Value,app.Mainapp.CurrentPlotData,app.WhatToDo,app.BasedOnERP,app.ShowAnayzedData,app.DataToExtractFromDropDown.Value,app.LowPassSettings,app.FilterType,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
            
        end

        % Menu selected function: LineWidthMeanMenu_2
        function LineWidthMeanMenu_2Selected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes_2,'linewidth', app.Mainapp,"MultipleERPLineWidthMean");
            waitfor(h);

            %% --------------------- Compute and Plot ---------------------
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                DataToCompute = app.Mainapp.Data.EventRelatedData(:,app.EventsToShow,:);
            else
                DataToCompute = app.Mainapp.Data.PreprocessedEventRelatedData(:,app.EventsToShow,:);
            end

            [app.TextArea.Value,app.Mainapp.CurrentPlotData] = Event_Module_PhaseSync_Main(DataToCompute,app.Mainapp.Data.Info.EventRelatedTime,app.PolarPlot,app.UIAxes_3,app.UIAxes,app.UIAxes_2,app.Mainapp.Data,app.ChannelToCompare,app.NarrowbandCutoffLowerHigherEditField.Value,app.NarrowbandFilterorderEditField.Value,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.Mainapp.PlotAppearance,app.Ccolormap,app.CalculationMethodDropDown.Value,app.ForceFilterOFFCheckBox.Value,app.ECHTFilterorderEditField.Value,app.Mainapp.CurrentPlotData,app.WhatToDo,app.BasedOnERP,app.ShowAnayzedData,app.DataToExtractFromDropDown.Value,app.LowPassSettings,app.FilterType,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
            
        end

        % Menu selected function: LineWidthTriggerMenu_2
        function LineWidthTriggerMenu_2Selected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes_2,'linewidth', app.Mainapp,"MultipleERPLineWidthTrigger");
            waitfor(h);

            %% --------------------- Compute and Plot ---------------------
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                DataToCompute = app.Mainapp.Data.EventRelatedData(:,app.EventsToShow,:);
            else
                DataToCompute = app.Mainapp.Data.PreprocessedEventRelatedData(:,app.EventsToShow,:);
            end

            [app.TextArea.Value,app.Mainapp.CurrentPlotData] = Event_Module_PhaseSync_Main(DataToCompute,app.Mainapp.Data.Info.EventRelatedTime,app.PolarPlot,app.UIAxes_3,app.UIAxes,app.UIAxes_2,app.Mainapp.Data,app.ChannelToCompare,app.NarrowbandCutoffLowerHigherEditField.Value,app.NarrowbandFilterorderEditField.Value,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.Mainapp.PlotAppearance,app.Ccolormap,app.CalculationMethodDropDown.Value,app.ForceFilterOFFCheckBox.Value,app.ECHTFilterorderEditField.Value,app.Mainapp.CurrentPlotData,app.WhatToDo,app.BasedOnERP,app.ShowAnayzedData,app.DataToExtractFromDropDown.Value,app.LowPassSettings,app.FilterType,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
            
        end

        % Menu selected function: ColorTriggerMenu_2
        function ColorTriggerMenu_2Selected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                %% --------------------- Compute and Plot ---------------------
                if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                    DataToCompute = app.Mainapp.Data.EventRelatedData(:,app.EventsToShow,:);
                else
                    DataToCompute = app.Mainapp.Data.PreprocessedEventRelatedData(:,app.EventsToShow,:);
                end
    
                [app.TextArea.Value,app.Mainapp.CurrentPlotData] = Event_Module_PhaseSync_Main(DataToCompute,app.Mainapp.Data.Info.EventRelatedTime,app.PolarPlot,app.UIAxes_3,app.UIAxes,app.UIAxes_2,app.Mainapp.Data,app.ChannelToCompare,app.NarrowbandCutoffLowerHigherEditField.Value,app.NarrowbandFilterorderEditField.Value,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.Mainapp.PlotAppearance,app.Ccolormap,app.CalculationMethodDropDown.Value,app.ForceFilterOFFCheckBox.Value,app.ECHTFilterorderEditField.Value,app.Mainapp.CurrentPlotData,app.WhatToDo,app.BasedOnERP,app.ShowAnayzedData,app.DataToExtractFromDropDown.Value,app.LowPassSettings,app.FilterType,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
            
            end

        end

        % Menu selected function: BackgorundColorMenu_2
        function BackgorundColorMenu_2Selected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                %% --------------------- Compute and Plot ---------------------
                if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                    DataToCompute = app.Mainapp.Data.EventRelatedData(:,app.EventsToShow,:);
                else
                    DataToCompute = app.Mainapp.Data.PreprocessedEventRelatedData(:,app.EventsToShow,:);
                end
    
                [app.TextArea.Value,app.Mainapp.CurrentPlotData] = Event_Module_PhaseSync_Main(DataToCompute,app.Mainapp.Data.Info.EventRelatedTime,app.PolarPlot,app.UIAxes_3,app.UIAxes,app.UIAxes_2,app.Mainapp.Data,app.ChannelToCompare,app.NarrowbandCutoffLowerHigherEditField.Value,app.NarrowbandFilterorderEditField.Value,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.Mainapp.PlotAppearance,app.Ccolormap,app.CalculationMethodDropDown.Value,app.ForceFilterOFFCheckBox.Value,app.ECHTFilterorderEditField.Value,app.Mainapp.CurrentPlotData,app.WhatToDo,app.BasedOnERP,app.ShowAnayzedData,app.DataToExtractFromDropDown.Value,app.LowPassSettings,app.FilterType,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
            
            end
        end

        % Menu selected function: XLabelMenu_3
        function XLabelMenu_3Selected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes_2,'xlabel', app.Mainapp,"MultipleERPWindowXLabel");
            waitfor(h);

            %% --------------------- Compute and Plot ---------------------
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                DataToCompute = app.Mainapp.Data.EventRelatedData(:,app.EventsToShow,:);
            else
                DataToCompute = app.Mainapp.Data.PreprocessedEventRelatedData(:,app.EventsToShow,:);
            end

            [app.TextArea.Value,app.Mainapp.CurrentPlotData] = Event_Module_PhaseSync_Main(DataToCompute,app.Mainapp.Data.Info.EventRelatedTime,app.PolarPlot,app.UIAxes_3,app.UIAxes,app.UIAxes_2,app.Mainapp.Data,app.ChannelToCompare,app.NarrowbandCutoffLowerHigherEditField.Value,app.NarrowbandFilterorderEditField.Value,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.Mainapp.PlotAppearance,app.Ccolormap,app.CalculationMethodDropDown.Value,app.ForceFilterOFFCheckBox.Value,app.ECHTFilterorderEditField.Value,app.Mainapp.CurrentPlotData,app.WhatToDo,app.BasedOnERP,app.ShowAnayzedData,app.DataToExtractFromDropDown.Value,app.LowPassSettings,app.FilterType,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
            
        end

        % Menu selected function: YLabelMenu_3
        function YLabelMenu_3Selected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes_2,'ylabel', app.Mainapp,"MultipleERPWindowYLabel");
            waitfor(h);
            
            %% --------------------- Compute and Plot ---------------------
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                DataToCompute = app.Mainapp.Data.EventRelatedData(:,app.EventsToShow,:);
            else
                DataToCompute = app.Mainapp.Data.PreprocessedEventRelatedData(:,app.EventsToShow,:);
            end

            [app.TextArea.Value,app.Mainapp.CurrentPlotData] = Event_Module_PhaseSync_Main(DataToCompute,app.Mainapp.Data.Info.EventRelatedTime,app.PolarPlot,app.UIAxes_3,app.UIAxes,app.UIAxes_2,app.Mainapp.Data,app.ChannelToCompare,app.NarrowbandCutoffLowerHigherEditField.Value,app.NarrowbandFilterorderEditField.Value,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.Mainapp.PlotAppearance,app.Ccolormap,app.CalculationMethodDropDown.Value,app.ForceFilterOFFCheckBox.Value,app.ECHTFilterorderEditField.Value,app.Mainapp.CurrentPlotData,app.WhatToDo,app.BasedOnERP,app.ShowAnayzedData,app.DataToExtractFromDropDown.Value,app.LowPassSettings,app.FilterType,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
            
        end

        % Menu selected function: LineWidthEventsMenu_3
        function LineWidthEventsMenu_3Selected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'linewidth', app.Mainapp,"SingleERPLineWidthEvents");
            waitfor(h);

            %% --------------------- Compute and Plot ---------------------
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                DataToCompute = app.Mainapp.Data.EventRelatedData(:,app.EventsToShow,:);
            else
                DataToCompute = app.Mainapp.Data.PreprocessedEventRelatedData(:,app.EventsToShow,:);
            end

            [app.TextArea.Value,app.Mainapp.CurrentPlotData] = Event_Module_PhaseSync_Main(DataToCompute,app.Mainapp.Data.Info.EventRelatedTime,app.PolarPlot,app.UIAxes_3,app.UIAxes,app.UIAxes_2,app.Mainapp.Data,app.ChannelToCompare,app.NarrowbandCutoffLowerHigherEditField.Value,app.NarrowbandFilterorderEditField.Value,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.Mainapp.PlotAppearance,app.Ccolormap,app.CalculationMethodDropDown.Value,app.ForceFilterOFFCheckBox.Value,app.ECHTFilterorderEditField.Value,app.Mainapp.CurrentPlotData,app.WhatToDo,app.BasedOnERP,app.ShowAnayzedData,app.DataToExtractFromDropDown.Value,app.LowPassSettings,app.FilterType,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
            
        end

        % Menu selected function: LineWidthMeanMenu
        function LineWidthMeanMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'linewidth', app.Mainapp,"SingleERPLineWidthMean");
            waitfor(h);

            %% --------------------- Compute and Plot ---------------------
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                DataToCompute = app.Mainapp.Data.EventRelatedData(:,app.EventsToShow,:);
            else
                DataToCompute = app.Mainapp.Data.PreprocessedEventRelatedData(:,app.EventsToShow,:);
            end

            [app.TextArea.Value,app.Mainapp.CurrentPlotData] = Event_Module_PhaseSync_Main(DataToCompute,app.Mainapp.Data.Info.EventRelatedTime,app.PolarPlot,app.UIAxes_3,app.UIAxes,app.UIAxes_2,app.Mainapp.Data,app.ChannelToCompare,app.NarrowbandCutoffLowerHigherEditField.Value,app.NarrowbandFilterorderEditField.Value,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.Mainapp.PlotAppearance,app.Ccolormap,app.CalculationMethodDropDown.Value,app.ForceFilterOFFCheckBox.Value,app.ECHTFilterorderEditField.Value,app.Mainapp.CurrentPlotData,app.WhatToDo,app.BasedOnERP,app.ShowAnayzedData,app.DataToExtractFromDropDown.Value,app.LowPassSettings,app.FilterType,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
            

        end

        % Menu selected function: LineWidthTriggerMenu
        function LineWidthTriggerMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'linewidth', app.Mainapp,"SingleERPLineWidthTrigger");
            waitfor(h);
            %% --------------------- Compute and Plot ---------------------
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                DataToCompute = app.Mainapp.Data.EventRelatedData(:,app.EventsToShow,:);
            else
                DataToCompute = app.Mainapp.Data.PreprocessedEventRelatedData(:,app.EventsToShow,:);
            end

            [app.TextArea.Value,app.Mainapp.CurrentPlotData] = Event_Module_PhaseSync_Main(DataToCompute,app.Mainapp.Data.Info.EventRelatedTime,app.PolarPlot,app.UIAxes_3,app.UIAxes,app.UIAxes_2,app.Mainapp.Data,app.ChannelToCompare,app.NarrowbandCutoffLowerHigherEditField.Value,app.NarrowbandFilterorderEditField.Value,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.Mainapp.PlotAppearance,app.Ccolormap,app.CalculationMethodDropDown.Value,app.ForceFilterOFFCheckBox.Value,app.ECHTFilterorderEditField.Value,app.Mainapp.CurrentPlotData,app.WhatToDo,app.BasedOnERP,app.ShowAnayzedData,app.DataToExtractFromDropDown.Value,app.LowPassSettings,app.FilterType,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
            
        end

        % Menu selected function: ColorEventsMenu_3
        function ColorEventsMenu_3Selected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                %% --------------------- Compute and Plot ---------------------
                if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                    DataToCompute = app.Mainapp.Data.EventRelatedData(:,app.EventsToShow,:);
                else
                    DataToCompute = app.Mainapp.Data.PreprocessedEventRelatedData(:,app.EventsToShow,:);
                end
    
                [app.TextArea.Value,app.Mainapp.CurrentPlotData] = Event_Module_PhaseSync_Main(DataToCompute,app.Mainapp.Data.Info.EventRelatedTime,app.PolarPlot,app.UIAxes_3,app.UIAxes,app.UIAxes_2,app.Mainapp.Data,app.ChannelToCompare,app.NarrowbandCutoffLowerHigherEditField.Value,app.NarrowbandFilterorderEditField.Value,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.Mainapp.PlotAppearance,app.Ccolormap,app.CalculationMethodDropDown.Value,app.ForceFilterOFFCheckBox.Value,app.ECHTFilterorderEditField.Value,app.Mainapp.CurrentPlotData,app.WhatToDo,app.BasedOnERP,app.ShowAnayzedData,app.DataToExtractFromDropDown.Value,app.LowPassSettings,app.FilterType,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
            
            end
  
        end

        % Menu selected function: ColorMeanMenu
        function ColorMeanMenuSelected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                %% --------------------- Compute and Plot ---------------------
                if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                    DataToCompute = app.Mainapp.Data.EventRelatedData(:,app.EventsToShow,:);
                else
                    DataToCompute = app.Mainapp.Data.PreprocessedEventRelatedData(:,app.EventsToShow,:);
                end

                [app.TextArea.Value,app.Mainapp.CurrentPlotData] = Event_Module_PhaseSync_Main(DataToCompute,app.Mainapp.Data.Info.EventRelatedTime,app.PolarPlot,app.UIAxes_3,app.UIAxes,app.UIAxes_2,app.Mainapp.Data,app.ChannelToCompare,app.NarrowbandCutoffLowerHigherEditField.Value,app.NarrowbandFilterorderEditField.Value,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.Mainapp.PlotAppearance,app.Ccolormap,app.CalculationMethodDropDown.Value,app.ForceFilterOFFCheckBox.Value,app.ECHTFilterorderEditField.Value,app.Mainapp.CurrentPlotData,app.WhatToDo,app.BasedOnERP,app.ShowAnayzedData,app.DataToExtractFromDropDown.Value,app.LowPassSettings,app.FilterType,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
            
            end           
           
        end

        % Menu selected function: ColorTriggerMenu
        function ColorTriggerMenuSelected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                %% --------------------- Compute and Plot ---------------------
                if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                    DataToCompute = app.Mainapp.Data.EventRelatedData(:,app.EventsToShow,:);
                else
                    DataToCompute = app.Mainapp.Data.PreprocessedEventRelatedData(:,app.EventsToShow,:);
                end
    
                [app.TextArea.Value,app.Mainapp.CurrentPlotData] = Event_Module_PhaseSync_Main(DataToCompute,app.Mainapp.Data.Info.EventRelatedTime,app.PolarPlot,app.UIAxes_3,app.UIAxes,app.UIAxes_2,app.Mainapp.Data,app.ChannelToCompare,app.NarrowbandCutoffLowerHigherEditField.Value,app.NarrowbandFilterorderEditField.Value,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.Mainapp.PlotAppearance,app.Ccolormap,app.CalculationMethodDropDown.Value,app.ForceFilterOFFCheckBox.Value,app.ECHTFilterorderEditField.Value,app.Mainapp.CurrentPlotData,app.WhatToDo,app.BasedOnERP,app.ShowAnayzedData,app.DataToExtractFromDropDown.Value,app.LowPassSettings,app.FilterType,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
            
            end
            
        end

        % Menu selected function: BackgorundColorMenu
        function BackgorundColorMenuSelected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                 app.Mainapp.PlotAppearance.ERPWindow.SingleERP.BackgroundColor = selectedColor;
                app.UIAxes.Color =  app.Mainapp.PlotAppearance.ERPWindow.SingleERP.BackgroundColor;
                app.UIAxes_2.Color =  app.Mainapp.PlotAppearance.ERPWindow.MultipleERP.BackgroundColor;
                app.UIAxes_2.FontSize =  app.Mainapp.PlotAppearance.ERPWindow.MultipleERP.FontSize;
            end
        end

        % Menu selected function: RESETMenu
        function RESETMenuSelected(app, event)
            app.UIAxes.NextPlot = "replace"; 
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add"; 

            %% --------------------- Compute and Plot ---------------------
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                DataToCompute = app.Mainapp.Data.EventRelatedData(:,app.EventsToShow,:);
            else
                DataToCompute = app.Mainapp.Data.PreprocessedEventRelatedData(:,app.EventsToShow,:);
            end

            [app.TextArea.Value,app.Mainapp.CurrentPlotData] = Event_Module_PhaseSync_Main(DataToCompute,app.Mainapp.Data.Info.EventRelatedTime,app.PolarPlot,app.UIAxes_3,app.UIAxes,app.UIAxes_2,app.Mainapp.Data,app.ChannelToCompare,app.NarrowbandCutoffLowerHigherEditField.Value,app.NarrowbandFilterorderEditField.Value,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.Mainapp.PlotAppearance,app.Ccolormap,app.CalculationMethodDropDown.Value,app.ForceFilterOFFCheckBox.Value,app.ECHTFilterorderEditField.Value,app.Mainapp.CurrentPlotData,app.WhatToDo,app.BasedOnERP,app.ShowAnayzedData,app.DataToExtractFromDropDown.Value,app.LowPassSettings,app.FilterType,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
            
        end

        % Menu selected function: RESETMenu_3
        function RESETMenu_3Selected(app, event)
            app.UIAxes_2.NextPlot = "replace"; 
            plot(app.UIAxes_2,0,0);
            app.UIAxes_2.NextPlot = "add"; 

            %% --------------------- Compute and Plot ---------------------
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                DataToCompute = app.Mainapp.Data.EventRelatedData(:,app.EventsToShow,:);
            else
                DataToCompute = app.Mainapp.Data.PreprocessedEventRelatedData(:,app.EventsToShow,:);
            end

            [app.TextArea.Value,app.Mainapp.CurrentPlotData] = Event_Module_PhaseSync_Main(DataToCompute,app.Mainapp.Data.Info.EventRelatedTime,app.PolarPlot,app.UIAxes_3,app.UIAxes,app.UIAxes_2,app.Mainapp.Data,app.ChannelToCompare,app.NarrowbandCutoffLowerHigherEditField.Value,app.NarrowbandFilterorderEditField.Value,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.Mainapp.PlotAppearance,app.Ccolormap,app.CalculationMethodDropDown.Value,app.ForceFilterOFFCheckBox.Value,app.ECHTFilterorderEditField.Value,app.Mainapp.CurrentPlotData,app.WhatToDo,app.BasedOnERP,app.ShowAnayzedData,app.DataToExtractFromDropDown.Value,app.LowPassSettings,app.FilterType,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
           
        end

        % Menu selected function: SaveCustomeAppearanceMenu
        function SaveCustomeAppearanceMenuSelected(app, event)
             
        end

        % Menu selected function: DeleteCustomeAppearanceMenu
        function DeleteCustomeAppearanceMenuSelected(app, event)
            
        end

        % Close request function: PhaseSynchroFigure
        function PhaseSynchroFigureCloseRequest(app, event)
            try
                if ~isempty(app.Mainapp.ProbeViewWindowHandle) % delete option from probe view 
                    for i = 1:length(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                        if strcmp(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Event Phase Synchronization')
                            app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items(i) = [];
                            break;
                        end
                    end
                else % if no probe view available
     
                end
                app.Mainapp.EventPhaseSynchro = [];
                delete(app)
            catch
                delete(app)
            end
            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed2(app, event)
            try
                if ~isempty(app.Mainapp.ProbeViewWindowHandle) % delete option from probe view 
                    for i = 1:length(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                        if strcmp(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Event Phase Synchronization')
                            app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items(i) = [];
                            break;
                        end
                    end
                else % if no probe view available
     
                end
                app.Mainapp.EventPhaseSynchro = [];
                delete(app)
            catch
                delete(app)
            end
        end

        % Value changed function: TrialSelectionMatlabExpressionsEditField
        function TrialSelectionMatlabExpressionsEditFieldValueChanged(app, event)
            
            %% --------------------- Check User Input ---------------------
            
            if contains(app.TrialSelectionMatlabExpressionsEditField.Value,',')
                if ~contains(app.TrialSelectionMatlabExpressionsEditField.Value,'[') || ~contains(app.TrialSelectionMatlabExpressionsEditField.Value,']')
                    app.TrialSelectionMatlabExpressionsEditField.Value = strcat('[',app.TrialSelectionMatlabExpressionsEditField.Value,']');
                end
            end
            
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                AlleventNr = size(app.Mainapp.Data.EventRelatedData,2);
                try
                    EventNr = eval(app.TrialSelectionMatlabExpressionsEditField.Value);
                catch
                    EventNr = 1:AlleventNr;
                    app.TrialSelectionMatlabExpressionsEditField.Value = strcat(num2str(min(EventNr)),':',num2str(max(EventNr)));
                end
    
                if length(EventNr)>AlleventNr || sum(EventNr>AlleventNr) ~= 0 || isempty(EventNr)
                    msgbox("Error: Trigger range exceeds existing number of triggers! Plotting all trigger.")
                    EventNr = 1:AlleventNr;
                    app.TrialSelectionMatlabExpressionsEditField.Value = strcat(num2str(min(EventNr)),':',num2str(max(EventNr)));
                end
            else
                AlleventNr = size(app.Mainapp.Data.PreprocessedEventRelatedData,2);
                try
                    EventNr = eval(app.TrialSelectionMatlabExpressionsEditField.Value);
                catch
                    EventNr = 1:AlleventNr;
                    app.TrialSelectionMatlabExpressionsEditField.Value = strcat(num2str(min(EventNr)),':',num2str(max(EventNr)));
                end
    
                if length(EventNr)>AlleventNr || sum(EventNr>AlleventNr) ~= 0 || isempty(EventNr)
                    msgbox("Error: Trigger range exceeds existing number of triggers! Plotting all trigger.")
                    EventNr = 1:AlleventNr;
                    app.TrialSelectionMatlabExpressionsEditField.Value = strcat(num2str(min(EventNr)),':',num2str(max(EventNr)));
                end
            end
            
            app.EventsToShow = eval(app.TrialSelectionMatlabExpressionsEditField.Value);

            %% --------------------- Compute and Plot ---------------------
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                DataToCompute = app.Mainapp.Data.EventRelatedData(:,app.EventsToShow,:);
            else
                DataToCompute = app.Mainapp.Data.PreprocessedEventRelatedData(:,app.EventsToShow,:);
            end

            [app.TextArea.Value,app.Mainapp.CurrentPlotData] = Event_Module_PhaseSync_Main(DataToCompute,app.Mainapp.Data.Info.EventRelatedTime,app.PolarPlot,app.UIAxes_3,app.UIAxes,app.UIAxes_2,app.Mainapp.Data,app.ChannelToCompare,app.NarrowbandCutoffLowerHigherEditField.Value,app.NarrowbandFilterorderEditField.Value,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.Mainapp.PlotAppearance,app.Ccolormap,app.CalculationMethodDropDown.Value,app.ForceFilterOFFCheckBox.Value,app.ECHTFilterorderEditField.Value,app.Mainapp.CurrentPlotData,app.WhatToDo,app.BasedOnERP,app.ShowAnayzedData,app.DataToExtractFromDropDown.Value,app.LowPassSettings,app.FilterType,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
            
        end

        % Value changed function: DataTypeDropDown
        function DataTypeDropDownValueChanged2(app, event)
            %% -------------------- Extract Event Related Data -------------------- 
            [app.Mainapp.Data,~] = Event_Module_Extract_Event_Related_Data(app.Mainapp.Data,app.EventChannelSelectionDropDown.Value,app.TimearoundEvent,app.DataToExtractFromDropDown.Value,app.DataTypeDropDown.Value);
            
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                app.EventNumberSelectionEditField_2.Value = strcat(num2str(size(app.Mainapp.Data.EventRelatedData,2)),' Trigger');
            else
                app.EventNumberSelectionEditField_2.Value = strcat(num2str(size(app.Mainapp.Data.PreprocessedEventRelatedData,2)),' Trigger');
            end

            %% -------------------- Populate event channel selection -------------------- 
            [app.TrialSelectionMatlabExpressionsEditField.Value] = Event_Module_Check_EventInput(app.TrialSelectionMatlabExpressionsEditField.Value,app.Mainapp.Data,app.EventChannelSelectionDropDown.Value,app.DataTypeDropDown.Value,0);
            app.EventsToShow = eval(app.TrialSelectionMatlabExpressionsEditField.Value);
            
            %% --------------------- Compute and Plot ---------------------
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                DataToCompute = app.Mainapp.Data.EventRelatedData(:,app.EventsToShow,:);
            else
                DataToCompute = app.Mainapp.Data.PreprocessedEventRelatedData(:,app.EventsToShow,:);
            end

            [app.TextArea.Value,app.Mainapp.CurrentPlotData] = Event_Module_PhaseSync_Main(DataToCompute,app.Mainapp.Data.Info.EventRelatedTime,app.PolarPlot,app.UIAxes_3,app.UIAxes,app.UIAxes_2,app.Mainapp.Data,app.ChannelToCompare,app.NarrowbandCutoffLowerHigherEditField.Value,app.NarrowbandFilterorderEditField.Value,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.Mainapp.PlotAppearance,app.Ccolormap,app.CalculationMethodDropDown.Value,app.ForceFilterOFFCheckBox.Value,app.ECHTFilterorderEditField.Value,app.Mainapp.CurrentPlotData,app.WhatToDo,app.BasedOnERP,app.ShowAnayzedData,app.DataToExtractFromDropDown.Value,app.LowPassSettings,app.FilterType,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
            
        end

        % Value changed function: CalculationMethodDropDown
        function CalculationMethodDropDownValueChanged(app, event)
            app.WhatToDo = 'All';
            
            if strcmp(app.CalculationMethodDropDown.Value,"Endpoint Corrected Hilbert Transform")
                app.ECHTFilterorderEditField.Enable = "on";
                app.ECHTFilterorderLabel.Text = "ECHT Filterorder";
                app.ECHTFilterorderEditField.Value = '2';
            elseif strcmp(app.CalculationMethodDropDown.Value,"Hilbert Transform")
                app.ECHTFilterorderEditField.Enable = "off";
            elseif strcmp(app.CalculationMethodDropDown.Value,"Wavelet Convolution")
                app.ECHTFilterorderEditField.Enable = "on";
                app.ECHTFilterorderLabel.Text = "Number Cycles";
                app.ECHTFilterorderEditField.Value = '20';
            end

            %% --------------------- Compute and Plot ---------------------
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                DataToCompute = app.Mainapp.Data.EventRelatedData(:,app.EventsToShow,:);
            else
                DataToCompute = app.Mainapp.Data.PreprocessedEventRelatedData(:,app.EventsToShow,:);
            end
            [app.TextArea.Value,app.Mainapp.CurrentPlotData] = Event_Module_PhaseSync_Main(DataToCompute,app.Mainapp.Data.Info.EventRelatedTime,app.PolarPlot,app.UIAxes_3,app.UIAxes,app.UIAxes_2,app.Mainapp.Data,app.ChannelToCompare,app.NarrowbandCutoffLowerHigherEditField.Value,app.NarrowbandFilterorderEditField.Value,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.Mainapp.PlotAppearance,app.Ccolormap,app.CalculationMethodDropDown.Value,app.ForceFilterOFFCheckBox.Value,app.ECHTFilterorderEditField.Value,app.Mainapp.CurrentPlotData,app.WhatToDo,app.BasedOnERP,app.ShowAnayzedData,app.DataToExtractFromDropDown.Value,app.LowPassSettings,app.FilterType,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
            
        end

        % Value changed function: NarrowbandCutoffLowerHigherEditField
        function NarrowbandCutoffLowerHigherEditFieldValueChanged(app, event)
            %% --------------------- Check ---------------------
            if isempty(app.NarrowbandCutoffLowerHigherEditField.Value)
                app.NarrowbandCutoffLowerHigherEditField.Value = event.PreviousValue;
            end
            %% --------------------- Auto-Adjust Filterorder ---------------------
            freqs = str2double(strsplit(app.NarrowbandCutoffLowerHigherEditField.Value,','));
            
            [app.NarrowbandFilterorderEditField.Value] = Utility_Determine_FIR_FilterOrder(app,freqs(1));
            
            app.WhatToDo = 'All';

            %% --------------------- Compute and Plot ---------------------
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                DataToCompute = app.Mainapp.Data.EventRelatedData(:,app.EventsToShow,:);
            else
                DataToCompute = app.Mainapp.Data.PreprocessedEventRelatedData(:,app.EventsToShow,:);
            end

            [app.TextArea.Value,app.Mainapp.CurrentPlotData] = Event_Module_PhaseSync_Main(DataToCompute,app.Mainapp.Data.Info.EventRelatedTime,app.PolarPlot,app.UIAxes_3,app.UIAxes,app.UIAxes_2,app.Mainapp.Data,app.ChannelToCompare,app.NarrowbandCutoffLowerHigherEditField.Value,app.NarrowbandFilterorderEditField.Value,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.Mainapp.PlotAppearance,app.Ccolormap,app.CalculationMethodDropDown.Value,app.ForceFilterOFFCheckBox.Value,app.ECHTFilterorderEditField.Value,app.Mainapp.CurrentPlotData,app.WhatToDo,app.BasedOnERP,app.ShowAnayzedData,app.DataToExtractFromDropDown.Value,app.LowPassSettings,app.FilterType,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
            
        end

        % Value changed function: NarrowbandFilterorderEditField
        function NarrowbandFilterorderEditFieldValueChanged(app, event)
            app.WhatToDo = 'All';
            
            %% --------------------- Check ---------------------
            if isempty(app.NarrowbandFilterorderEditField.Value)
                app.NarrowbandFilterorderEditField.Value = event.PreviousValue;
            else
                if str2double(app.NarrowbandFilterorderEditField.Value) < 0 || isnan(str2double(app.NarrowbandFilterorderEditField.Value))
                    app.NarrowbandFilterorderEditField.Value = event.PreviousValue;
                end
            end

            %% --------------------- Compute and Plot ---------------------
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                DataToCompute = app.Mainapp.Data.EventRelatedData(:,app.EventsToShow,:);
            else
                DataToCompute = app.Mainapp.Data.PreprocessedEventRelatedData(:,app.EventsToShow,:);
            end

            [app.TextArea.Value,app.Mainapp.CurrentPlotData] = Event_Module_PhaseSync_Main(DataToCompute,app.Mainapp.Data.Info.EventRelatedTime,app.PolarPlot,app.UIAxes_3,app.UIAxes,app.UIAxes_2,app.Mainapp.Data,app.ChannelToCompare,app.NarrowbandCutoffLowerHigherEditField.Value,app.NarrowbandFilterorderEditField.Value,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.Mainapp.PlotAppearance,app.Ccolormap,app.CalculationMethodDropDown.Value,app.ForceFilterOFFCheckBox.Value,app.ECHTFilterorderEditField.Value,app.Mainapp.CurrentPlotData,app.WhatToDo,app.BasedOnERP,app.ShowAnayzedData,app.DataToExtractFromDropDown.Value,app.LowPassSettings,app.FilterType,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
            
        end

        % Value changed function: ECHTFilterorderEditField
        function ECHTFilterorderEditFieldValueChanged(app, event)
            %% --------------------- Check ---------------------
            if isempty(app.ECHTFilterorderEditField.Value)
                app.ECHTFilterorderEditField.Value = event.PreviousValue;
            else
                if str2double(app.ECHTFilterorderEditField.Value) < 0 || isnan(str2double(app.ECHTFilterorderEditField.Value))
                    app.ECHTFilterorderEditField.Value = event.PreviousValue;
                end
            end

            %% --------------------- Compute and Plot ---------------------
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                DataToCompute = app.Mainapp.Data.EventRelatedData(:,app.EventsToShow,:);
            else
                DataToCompute = app.Mainapp.Data.PreprocessedEventRelatedData(:,app.EventsToShow,:);
            end

            [app.TextArea.Value,app.Mainapp.CurrentPlotData] = Event_Module_PhaseSync_Main(DataToCompute,app.Mainapp.Data.Info.EventRelatedTime,app.PolarPlot,app.UIAxes_3,app.UIAxes,app.UIAxes_2,app.Mainapp.Data,app.ChannelToCompare,app.NarrowbandCutoffLowerHigherEditField.Value,app.NarrowbandFilterorderEditField.Value,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.Mainapp.PlotAppearance,app.Ccolormap,app.CalculationMethodDropDown.Value,app.ForceFilterOFFCheckBox.Value,app.ECHTFilterorderEditField.Value,app.Mainapp.CurrentPlotData,app.WhatToDo,app.BasedOnERP,app.ShowAnayzedData,app.DataToExtractFromDropDown.Value,app.LowPassSettings,app.FilterType,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
            
        end

        % Value changed function: ForceFilterOFFCheckBox
        function ForceFilterOFFCheckBoxValueChanged(app, event)
            app.WhatToDo = 'All';
            %% --------------------- Compute and Plot ---------------------
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                DataToCompute = app.Mainapp.Data.EventRelatedData(:,app.EventsToShow,:);
            else
                DataToCompute = app.Mainapp.Data.PreprocessedEventRelatedData(:,app.EventsToShow,:);
            end

            [app.TextArea.Value,app.Mainapp.CurrentPlotData] = Event_Module_PhaseSync_Main(DataToCompute,app.Mainapp.Data.Info.EventRelatedTime,app.PolarPlot,app.UIAxes_3,app.UIAxes,app.UIAxes_2,app.Mainapp.Data,app.ChannelToCompare,app.NarrowbandCutoffLowerHigherEditField.Value,app.NarrowbandFilterorderEditField.Value,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.Mainapp.PlotAppearance,app.Ccolormap,app.CalculationMethodDropDown.Value,app.ForceFilterOFFCheckBox.Value,app.ECHTFilterorderEditField.Value,app.Mainapp.CurrentPlotData,app.WhatToDo,app.BasedOnERP,app.ShowAnayzedData,app.DataToExtractFromDropDown.Value,app.LowPassSettings,app.FilterType,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
            
        end

        % Value changed function: ChannelSelectionDropDown_2
        function ChannelSelectionDropDown_2ValueChanged(app, event)
            app.ChannelToCompare = [str2double(app.ChannelSelectionDropDown.Value),str2double(app.ChannelSelectionDropDown_2.Value)];
            
            app.WhatToDo = 'ChannelChange';
            
            %% --------------------- Compute and Plot ---------------------
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                DataToCompute = app.Mainapp.Data.EventRelatedData(:,app.EventsToShow,:);
            else
                DataToCompute = app.Mainapp.Data.PreprocessedEventRelatedData(:,app.EventsToShow,:);
            end

            [app.TextArea.Value,app.Mainapp.CurrentPlotData] = Event_Module_PhaseSync_Main(DataToCompute,app.Mainapp.Data.Info.EventRelatedTime,app.PolarPlot,app.UIAxes_3,app.UIAxes,app.UIAxes_2,app.Mainapp.Data,app.ChannelToCompare,app.NarrowbandCutoffLowerHigherEditField.Value,app.NarrowbandFilterorderEditField.Value,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.Mainapp.PlotAppearance,app.Ccolormap,app.CalculationMethodDropDown.Value,app.ForceFilterOFFCheckBox.Value,app.ECHTFilterorderEditField.Value,app.Mainapp.CurrentPlotData,app.WhatToDo,app.BasedOnERP,app.ShowAnayzedData,app.DataToExtractFromDropDown.Value,app.LowPassSettings,app.FilterType,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
            
            app.WhatToDo = 'All';
        end

        % Value changed function: ChannelSelectionDropDown
        function ChannelSelectionDropDownValueChanged(app, event)
            app.ChannelToCompare = [str2double(app.ChannelSelectionDropDown.Value),str2double(app.ChannelSelectionDropDown_2.Value)];
            
            app.WhatToDo = 'ChannelChange';
            
            %% --------------------- Compute and Plot ---------------------
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                DataToCompute = app.Mainapp.Data.EventRelatedData(:,app.EventsToShow,:);
            else
                DataToCompute = app.Mainapp.Data.PreprocessedEventRelatedData(:,app.EventsToShow,:);
            end

            [app.TextArea.Value,app.Mainapp.CurrentPlotData] = Event_Module_PhaseSync_Main(DataToCompute,app.Mainapp.Data.Info.EventRelatedTime,app.PolarPlot,app.UIAxes_3,app.UIAxes,app.UIAxes_2,app.Mainapp.Data,app.ChannelToCompare,app.NarrowbandCutoffLowerHigherEditField.Value,app.NarrowbandFilterorderEditField.Value,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.Mainapp.PlotAppearance,app.Ccolormap,app.CalculationMethodDropDown.Value,app.ForceFilterOFFCheckBox.Value,app.ECHTFilterorderEditField.Value,app.Mainapp.CurrentPlotData,app.WhatToDo,app.BasedOnERP,app.ShowAnayzedData,app.DataToExtractFromDropDown.Value,app.LowPassSettings,app.FilterType,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
            
            app.WhatToDo = 'All';
        end

        % Menu selected function: ONMenu
        function ONMenuSelected(app, event)
            app.ShowAnayzedData = 1;

            app.WhatToDo = 'AnglesEnvelops';

            %% --------------------- Compute and Plot ---------------------
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                DataToCompute = app.Mainapp.Data.EventRelatedData(:,app.EventsToShow,:);
            else
                DataToCompute = app.Mainapp.Data.PreprocessedEventRelatedData(:,app.EventsToShow,:);
            end
            [app.TextArea.Value,app.Mainapp.CurrentPlotData] = Event_Module_PhaseSync_Main(DataToCompute,app.Mainapp.Data.Info.EventRelatedTime,app.PolarPlot,app.UIAxes_3,app.UIAxes,app.UIAxes_2,app.Mainapp.Data,app.ChannelToCompare,app.NarrowbandCutoffLowerHigherEditField.Value,app.NarrowbandFilterorderEditField.Value,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.Mainapp.PlotAppearance,app.Ccolormap,app.CalculationMethodDropDown.Value,app.ForceFilterOFFCheckBox.Value,app.ECHTFilterorderEditField.Value,app.Mainapp.CurrentPlotData,app.WhatToDo,app.BasedOnERP,app.ShowAnayzedData,app.DataToExtractFromDropDown.Value,app.LowPassSettings,app.FilterType,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
            
            app.WhatToDo = 'All';
        end

        % Menu selected function: OFFMenu
        function OFFMenuSelected(app, event)
            app.ShowAnayzedData = 0;

            app.WhatToDo = 'AnglesEnvelops';

            %% --------------------- Compute and Plot ---------------------
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                DataToCompute = app.Mainapp.Data.EventRelatedData(:,app.EventsToShow,:);
            else
                DataToCompute = app.Mainapp.Data.PreprocessedEventRelatedData(:,app.EventsToShow,:);
            end

            [app.TextArea.Value,app.Mainapp.CurrentPlotData] = Event_Module_PhaseSync_Main(DataToCompute,app.Mainapp.Data.Info.EventRelatedTime,app.PolarPlot,app.UIAxes_3,app.UIAxes,app.UIAxes_2,app.Mainapp.Data,app.ChannelToCompare,app.NarrowbandCutoffLowerHigherEditField.Value,app.NarrowbandFilterorderEditField.Value,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.Mainapp.PlotAppearance,app.Ccolormap,app.CalculationMethodDropDown.Value,app.ForceFilterOFFCheckBox.Value,app.ECHTFilterorderEditField.Value,app.Mainapp.CurrentPlotData,app.WhatToDo,app.BasedOnERP,app.ShowAnayzedData,app.DataToExtractFromDropDown.Value,app.LowPassSettings,app.FilterType,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
            
            app.WhatToDo = 'All';
        end

        % Value changed function: DataToExtractFromDropDown
        function DataToExtractFromDropDownValueChanged(app, event)
           
            %% -------------------- Extract Event Related Data -------------------- 
            [app.Mainapp.Data,~] = Event_Module_Extract_Event_Related_Data(app.Mainapp.Data,app.EventChannelSelectionDropDown.Value,app.TimearoundEvent,app.DataToExtractFromDropDown.Value,app.DataTypeDropDown.Value);
            
            %% --------------------- Compute and Plot ---------------------
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                DataToCompute = app.Mainapp.Data.EventRelatedData(:,app.EventsToShow,:);
            else
                DataToCompute = app.Mainapp.Data.PreprocessedEventRelatedData(:,app.EventsToShow,:);
            end

            [app.TextArea.Value,app.Mainapp.CurrentPlotData] = Event_Module_PhaseSync_Main(DataToCompute,app.Mainapp.Data.Info.EventRelatedTime,app.PolarPlot,app.UIAxes_3,app.UIAxes,app.UIAxes_2,app.Mainapp.Data,app.ChannelToCompare,app.NarrowbandCutoffLowerHigherEditField.Value,app.NarrowbandFilterorderEditField.Value,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.Mainapp.PlotAppearance,app.Ccolormap,app.CalculationMethodDropDown.Value,app.ForceFilterOFFCheckBox.Value,app.ECHTFilterorderEditField.Value,app.Mainapp.CurrentPlotData,app.WhatToDo,app.BasedOnERP,app.ShowAnayzedData,app.DataToExtractFromDropDown.Value,app.LowPassSettings,app.FilterType,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
            
        end

        % Value changed function: EventChannelSelectionDropDown
        function EventChannelSelectionDropDownValueChanged(app, event)
            %% -------------------- Check if prepro event related data found and can be offered -------------------- 
            [texttoshow,Value] = Event_Module_Check_Event_Preprocessing(app.Mainapp.Data,app.EventChannelSelectionDropDown.Value,app.DataTypeDropDown.Value);
            app.DataTypeDropDown.Items = {};
            app.DataTypeDropDown.Items = texttoshow;
            app.DataTypeDropDown.Value = Value;

            %% -------------------- Extract Event Related Data -------------------- 
            [app.Mainapp.Data,~] = Event_Module_Extract_Event_Related_Data(app.Mainapp.Data,app.EventChannelSelectionDropDown.Value,app.TimearoundEvent,app.DataToExtractFromDropDown.Value,app.DataTypeDropDown.Value);
            
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                app.EventNumberSelectionEditField_2.Value = strcat(num2str(size(app.Mainapp.Data.EventRelatedData,2)),' Trigger');
            else
                app.EventNumberSelectionEditField_2.Value = strcat(num2str(size(app.Mainapp.Data.PreprocessedEventRelatedData,2)),' Trigger');
            end

            %% -------------------- Populate event channel selection -------------------- 
            [app.TrialSelectionMatlabExpressionsEditField.Value] = Event_Module_Check_EventInput(app.TrialSelectionMatlabExpressionsEditField.Value,app.Mainapp.Data,app.EventChannelSelectionDropDown.Value,app.DataTypeDropDown.Value,1);
            app.EventsToShow = eval(app.TrialSelectionMatlabExpressionsEditField.Value);

            %% --------------------- Compute and Plot ---------------------
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                DataToCompute = app.Mainapp.Data.EventRelatedData(:,app.EventsToShow,:);
            else
                DataToCompute = app.Mainapp.Data.PreprocessedEventRelatedData(:,app.EventsToShow,:);
            end

            [app.TextArea.Value,app.Mainapp.CurrentPlotData] = Event_Module_PhaseSync_Main(DataToCompute,app.Mainapp.Data.Info.EventRelatedTime,app.PolarPlot,app.UIAxes_3,app.UIAxes,app.UIAxes_2,app.Mainapp.Data,app.ChannelToCompare,app.NarrowbandCutoffLowerHigherEditField.Value,app.NarrowbandFilterorderEditField.Value,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.Mainapp.PlotAppearance,app.Ccolormap,app.CalculationMethodDropDown.Value,app.ForceFilterOFFCheckBox.Value,app.ECHTFilterorderEditField.Value,app.Mainapp.CurrentPlotData,app.WhatToDo,app.BasedOnERP,app.ShowAnayzedData,app.DataToExtractFromDropDown.Value,app.LowPassSettings,app.FilterType,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
            
        end

        % Menu selected function: ChangeLowPassParameterMenu
        function ChangeLowPassParameterMenuSelected(app, event)
            app.ChangeFilterSettingsWindow = Change_Filter_Options_Window(app,app.Mainapp,"LowPass");

            uiwait(app.ChangeFilterSettingsWindow.ChangeFilterOptionsUIFigure);

            if isvalid(app.ChangeFilterSettingsWindow)
                if ~isempty(app.ChangeFilterSettingsWindow)
                    delete(app.ChangeFilterSettingsWindow)
                    msgbox(strcat('Low Pass filter settings succesfully changed to cutoff: ',num2str(app.LowPassSettings.Cutoff),'; filter order: ',num2str(app.LowPassSettings.FilterOrder)))
                end
            end

            app.WhatToDo = 'All';

            %% --------------------- Compute and Plot ---------------------
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                DataToCompute = app.Mainapp.Data.EventRelatedData(:,app.EventsToShow,:);
            else
                DataToCompute = app.Mainapp.Data.PreprocessedEventRelatedData(:,app.EventsToShow,:);
            end

            [app.TextArea.Value,app.Mainapp.CurrentPlotData] = Event_Module_PhaseSync_Main(DataToCompute,app.Mainapp.Data.Info.EventRelatedTime,app.PolarPlot,app.UIAxes_3,app.UIAxes,app.UIAxes_2,app.Mainapp.Data,app.ChannelToCompare,app.NarrowbandCutoffLowerHigherEditField.Value,app.NarrowbandFilterorderEditField.Value,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.Mainapp.PlotAppearance,app.Ccolormap,app.CalculationMethodDropDown.Value,app.ForceFilterOFFCheckBox.Value,app.ECHTFilterorderEditField.Value,app.Mainapp.CurrentPlotData,app.WhatToDo,app.BasedOnERP,app.ShowAnayzedData,app.DataToExtractFromDropDown.Value,app.LowPassSettings,app.FilterType,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
            
        end

        % Menu selected function: ButterworthMenu
        function ButterworthMenuSelected(app, event)
            app.FilterType = 'Butter';
            
            app.NarrowbandFilterorderEditField.Value = '4';

            app.WhatToDo = 'All';

            % set up filter options
            app.ButterworthMenu.ForegroundColor = [0,1,0];
            app.FIRMenu.ForegroundColor = [1,0,0];

             %% --------------------- Compute and Plot ---------------------
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                DataToCompute = app.Mainapp.Data.EventRelatedData(:,app.EventsToShow,:);
            else
                DataToCompute = app.Mainapp.Data.PreprocessedEventRelatedData(:,app.EventsToShow,:);
            end

            [app.TextArea.Value,app.Mainapp.CurrentPlotData] = Event_Module_PhaseSync_Main(DataToCompute,app.Mainapp.Data.Info.EventRelatedTime,app.PolarPlot,app.UIAxes_3,app.UIAxes,app.UIAxes_2,app.Mainapp.Data,app.ChannelToCompare,app.NarrowbandCutoffLowerHigherEditField.Value,app.NarrowbandFilterorderEditField.Value,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.Mainapp.PlotAppearance,app.Ccolormap,app.CalculationMethodDropDown.Value,app.ForceFilterOFFCheckBox.Value,app.ECHTFilterorderEditField.Value,app.Mainapp.CurrentPlotData,app.WhatToDo,app.BasedOnERP,app.ShowAnayzedData,app.DataToExtractFromDropDown.Value,app.LowPassSettings,app.FilterType,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
            
        end

        % Menu selected function: FIRMenu
        function FIRMenuSelected(app, event)
            app.FilterType = 'FIR';

            freqs = str2double(strsplit(app.NarrowbandCutoffLowerHigherEditField.Value,','));
            
            [app.NarrowbandFilterorderEditField.Value] = Utility_Determine_FIR_FilterOrder(app,freqs(1));
            
            % set up filter options
            app.ButterworthMenu.ForegroundColor = [1,0,0];
            app.FIRMenu.ForegroundColor = [0,1,0];

            app.WhatToDo = 'All';

             %% --------------------- Compute and Plot ---------------------
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                DataToCompute = app.Mainapp.Data.EventRelatedData(:,app.EventsToShow,:);
            else
                DataToCompute = app.Mainapp.Data.PreprocessedEventRelatedData(:,app.EventsToShow,:);
            end

            [app.TextArea.Value,app.Mainapp.CurrentPlotData] = Event_Module_PhaseSync_Main(DataToCompute,app.Mainapp.Data.Info.EventRelatedTime,app.PolarPlot,app.UIAxes_3,app.UIAxes,app.UIAxes_2,app.Mainapp.Data,app.ChannelToCompare,app.NarrowbandCutoffLowerHigherEditField.Value,app.NarrowbandFilterorderEditField.Value,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.Mainapp.PlotAppearance,app.Ccolormap,app.CalculationMethodDropDown.Value,app.ForceFilterOFFCheckBox.Value,app.ECHTFilterorderEditField.Value,app.Mainapp.CurrentPlotData,app.WhatToDo,app.BasedOnERP,app.ShowAnayzedData,app.DataToExtractFromDropDown.Value,app.LowPassSettings,app.FilterType,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
            
        end

        % Value changed function: EventNumberSelectionEditField
        function EventNumberSelectionEditFieldValueChanged(app, event)
            %% --------------------- Compute and Plot ---------------------
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                DataToCompute = app.Mainapp.Data.EventRelatedData(:,app.EventsToShow,:);
            else
                DataToCompute = app.Mainapp.Data.PreprocessedEventRelatedData(:,app.EventsToShow,:);
            end

            [app.TextArea.Value,app.Mainapp.CurrentPlotData] = Event_Module_PhaseSync_Main(DataToCompute,app.Mainapp.Data.Info.EventRelatedTime,app.PolarPlot,app.UIAxes_3,app.UIAxes,app.UIAxes_2,app.Mainapp.Data,app.ChannelToCompare,app.NarrowbandCutoffLowerHigherEditField.Value,app.NarrowbandFilterorderEditField.Value,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.Mainapp.PlotAppearance,app.Ccolormap,app.CalculationMethodDropDown.Value,app.ForceFilterOFFCheckBox.Value,app.ECHTFilterorderEditField.Value,app.Mainapp.CurrentPlotData,app.WhatToDo,app.BasedOnERP,app.ShowAnayzedData,app.DataToExtractFromDropDown.Value,app.LowPassSettings,app.FilterType,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
            
            
        end

        % Value changed function: BaselineNormalizeCheckBox
        function BaselineNormalizeCheckBoxValueChanged(app, event)
            %% --------------------- Compute and Plot ---------------------
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                DataToCompute = app.Mainapp.Data.EventRelatedData(:,app.EventsToShow,:);
            else
                DataToCompute = app.Mainapp.Data.PreprocessedEventRelatedData(:,app.EventsToShow,:);
            end

            [app.TextArea.Value,app.Mainapp.CurrentPlotData] = Event_Module_PhaseSync_Main(DataToCompute,app.Mainapp.Data.Info.EventRelatedTime,app.PolarPlot,app.UIAxes_3,app.UIAxes,app.UIAxes_2,app.Mainapp.Data,app.ChannelToCompare,app.NarrowbandCutoffLowerHigherEditField.Value,app.NarrowbandFilterorderEditField.Value,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.Mainapp.PlotAppearance,app.Ccolormap,app.CalculationMethodDropDown.Value,app.ForceFilterOFFCheckBox.Value,app.ECHTFilterorderEditField.Value,app.Mainapp.CurrentPlotData,app.WhatToDo,app.BasedOnERP,app.ShowAnayzedData,app.DataToExtractFromDropDown.Value,app.LowPassSettings,app.FilterType,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
            
            
        end

        % Menu selected function: SVGMenu
        function SVGMenuSelected(app, event)
            [file, path] = uiputfile('*.svg', 'Save as');

            exportgraphics(app.UIAxes, fullfile(path,file), 'ContentType', 'vector')
        end

        % Menu selected function: MiddlePlotMenu
        function MiddlePlotMenuSelected(app, event)
            [file, path] = uiputfile('*.svg', 'Save as');

            exportgraphics(app.UIAxes_2, fullfile(path,file), 'ContentType', 'vector')
        end

        % Menu selected function: BottomLeftPlotMenu
        function BottomLeftPlotMenuSelected(app, event)
            [file, path] = uiputfile('*.svg', 'Save as');

            exportgraphics(app.UIAxes_3, fullfile(path,file), 'ContentType', 'vector')
        end

        % Menu selected function: BottomRightPlotMenu
        function BottomRightPlotMenuSelected(app, event)
            [file, path] = uiputfile('*.svg', 'Save as');

            exportgraphics(app.Panel, fullfile(path,file), 'ContentType', 'vector')
        end

        % Menu selected function: TopPlotMenu_2
        function TopPlotMenu_2Selected(app, event)
            [file, path] = uiputfile('*.eps', 'Save as');

            exportgraphics(app.UIAxes, fullfile(path,file), 'ContentType', 'vector')
        end

        % Menu selected function: MiddlePlotMenu_2
        function MiddlePlotMenu_2Selected(app, event)
            [file, path] = uiputfile('*.eps', 'Save as');

            exportgraphics(app.UIAxes_2, fullfile(path,file), 'ContentType', 'vector')
        end

        % Menu selected function: BottomLeftPlotMenu_2
        function BottomLeftPlotMenu_2Selected(app, event)
            [file, path] = uiputfile('*.eps', 'Save as');

            exportgraphics(app.UIAxes_3, fullfile(path,file), 'ContentType', 'vector')
        end

        % Menu selected function: BottomRightPlotMenu_2
        function BottomRightPlotMenu_2Selected(app, event)
            [file, path] = uiputfile('*.eps', 'Save as');

            exportgraphics(app.Panel, fullfile(path,file), 'ContentType', 'vector')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PhaseSynchroFigure = uifigure('Visible', 'off');
            app.PhaseSynchroFigure.Name = 'Event Phase Synchronization';
            app.PhaseSynchroFigure.Icon = 'Logo.png';
            app.PhaseSynchroFigure.CloseRequestFcn = createCallbackFcn(app, @PhaseSynchroFigureCloseRequest, true);
            app.SnapshotMenu = uimenu(app.PhaseSynchroFigure);
            app.SnapshotMenu.Text = '   Snapshot   ';
            app.SaveSnapshotofWindowMenu = uimenu(app.SnapshotMenu);
            app.SaveSnapshotofWindowMenu.Text = 'Save Snapshot of Window';
            app.PNGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.PNGMenu.MenuSelectedFcn = createCallbackFcn(app, @PNGMenuSelected, true);
            app.PNGMenu.Text = 'PNG';
            app.JPGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.JPGMenu.MenuSelectedFcn = createCallbackFcn(app, @JPGMenuSelected, true);
            app.JPGMenu.Text = 'JPG';
            app.SVGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.SVGMenu.MenuSelectedFcn = createCallbackFcn(app, @SVGMenuSelected, true);
            app.SVGMenu.Text = 'SVG';
            app.TopPlotMenu = uimenu(app.SVGMenu);
            app.TopPlotMenu.Text = 'Top Plot';
            app.MiddlePlotMenu = uimenu(app.SVGMenu);
            app.MiddlePlotMenu.MenuSelectedFcn = createCallbackFcn(app, @MiddlePlotMenuSelected, true);
            app.MiddlePlotMenu.Text = 'Middle Plot';
            app.BottomLeftPlotMenu = uimenu(app.SVGMenu);
            app.BottomLeftPlotMenu.MenuSelectedFcn = createCallbackFcn(app, @BottomLeftPlotMenuSelected, true);
            app.BottomLeftPlotMenu.Text = 'Bottom Left Plot';
            app.BottomRightPlotMenu = uimenu(app.SVGMenu);
            app.BottomRightPlotMenu.MenuSelectedFcn = createCallbackFcn(app, @BottomRightPlotMenuSelected, true);
            app.BottomRightPlotMenu.Text = 'Bottom Right Plot';
            app.EPSMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.EPSMenu.Text = 'EPS';
            app.TopPlotMenu_2 = uimenu(app.EPSMenu);
            app.TopPlotMenu_2.MenuSelectedFcn = createCallbackFcn(app, @TopPlotMenu_2Selected, true);
            app.TopPlotMenu_2.Text = 'Top Plot';
            app.MiddlePlotMenu_2 = uimenu(app.EPSMenu);
            app.MiddlePlotMenu_2.MenuSelectedFcn = createCallbackFcn(app, @MiddlePlotMenu_2Selected, true);
            app.MiddlePlotMenu_2.Text = 'Middle Plot';
            app.BottomLeftPlotMenu_2 = uimenu(app.EPSMenu);
            app.BottomLeftPlotMenu_2.MenuSelectedFcn = createCallbackFcn(app, @BottomLeftPlotMenu_2Selected, true);
            app.BottomLeftPlotMenu_2.Text = 'Bottom Left Plot';
            app.BottomRightPlotMenu_2 = uimenu(app.EPSMenu);
            app.BottomRightPlotMenu_2.MenuSelectedFcn = createCallbackFcn(app, @BottomRightPlotMenu_2Selected, true);
            app.BottomRightPlotMenu_2.Text = 'Bottom Right Plot';
            app.PlotMenu = uimenu(app.PhaseSynchroFigure);
            app.PlotMenu.Text = '   Plot   ';
            app.ChangePlotPropertiesMenu = uimenu(app.PlotMenu);
            app.ChangePlotPropertiesMenu.Text = 'Change Plot Properties';
            app.SingleERPPlotMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.SingleERPPlotMenu.Text = 'Single ERP Plot';
            app.FontSizeMenu = uimenu(app.SingleERPPlotMenu);
            app.FontSizeMenu.MenuSelectedFcn = createCallbackFcn(app, @FontSizeMenuSelected, true);
            app.FontSizeMenu.Text = 'FontSize';
            app.LineWidthEventsMenu_3 = uimenu(app.SingleERPPlotMenu);
            app.LineWidthEventsMenu_3.MenuSelectedFcn = createCallbackFcn(app, @LineWidthEventsMenu_3Selected, true);
            app.LineWidthEventsMenu_3.Text = 'LineWidth Events';
            app.LineWidthMeanMenu = uimenu(app.SingleERPPlotMenu);
            app.LineWidthMeanMenu.MenuSelectedFcn = createCallbackFcn(app, @LineWidthMeanMenuSelected, true);
            app.LineWidthMeanMenu.Text = 'LineWidth Mean';
            app.LineWidthTriggerMenu = uimenu(app.SingleERPPlotMenu);
            app.LineWidthTriggerMenu.MenuSelectedFcn = createCallbackFcn(app, @LineWidthTriggerMenuSelected, true);
            app.LineWidthTriggerMenu.Text = 'LineWidth Trigger';
            app.ColorEventsMenu_3 = uimenu(app.SingleERPPlotMenu);
            app.ColorEventsMenu_3.MenuSelectedFcn = createCallbackFcn(app, @ColorEventsMenu_3Selected, true);
            app.ColorEventsMenu_3.Text = 'Color Events';
            app.ColorMeanMenu = uimenu(app.SingleERPPlotMenu);
            app.ColorMeanMenu.MenuSelectedFcn = createCallbackFcn(app, @ColorMeanMenuSelected, true);
            app.ColorMeanMenu.Text = 'Color Mean';
            app.ColorTriggerMenu = uimenu(app.SingleERPPlotMenu);
            app.ColorTriggerMenu.MenuSelectedFcn = createCallbackFcn(app, @ColorTriggerMenuSelected, true);
            app.ColorTriggerMenu.Text = 'Color Trigger';
            app.BackgorundColorMenu = uimenu(app.SingleERPPlotMenu);
            app.BackgorundColorMenu.MenuSelectedFcn = createCallbackFcn(app, @BackgorundColorMenuSelected, true);
            app.BackgorundColorMenu.Text = 'Backgorund Color';
            app.XLabelMenu = uimenu(app.SingleERPPlotMenu);
            app.XLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @XLabelMenuSelected, true);
            app.XLabelMenu.Text = 'XLabel';
            app.YLabelMenu = uimenu(app.SingleERPPlotMenu);
            app.YLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @YLabelMenuSelected, true);
            app.YLabelMenu.Text = 'YLabel';
            app.RESETMenu = uimenu(app.SingleERPPlotMenu);
            app.RESETMenu.MenuSelectedFcn = createCallbackFcn(app, @RESETMenuSelected, true);
            app.RESETMenu.Text = 'RESET';
            app.MultipleERPPlotMenu_2 = uimenu(app.ChangePlotPropertiesMenu);
            app.MultipleERPPlotMenu_2.Text = 'Multiple ERP Plot';
            app.FontSizeMenu_3 = uimenu(app.MultipleERPPlotMenu_2);
            app.FontSizeMenu_3.MenuSelectedFcn = createCallbackFcn(app, @FontSizeMenu_3Selected, true);
            app.FontSizeMenu_3.Text = 'FontSize';
            app.LineWidthMeanMenu_2 = uimenu(app.MultipleERPPlotMenu_2);
            app.LineWidthMeanMenu_2.MenuSelectedFcn = createCallbackFcn(app, @LineWidthMeanMenu_2Selected, true);
            app.LineWidthMeanMenu_2.Text = 'LineWidth Mean';
            app.LineWidthTriggerMenu_2 = uimenu(app.MultipleERPPlotMenu_2);
            app.LineWidthTriggerMenu_2.MenuSelectedFcn = createCallbackFcn(app, @LineWidthTriggerMenu_2Selected, true);
            app.LineWidthTriggerMenu_2.Text = 'LineWidth Trigger';
            app.ColorTriggerMenu_2 = uimenu(app.MultipleERPPlotMenu_2);
            app.ColorTriggerMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ColorTriggerMenu_2Selected, true);
            app.ColorTriggerMenu_2.Text = 'Color Trigger';
            app.BackgorundColorMenu_2 = uimenu(app.MultipleERPPlotMenu_2);
            app.BackgorundColorMenu_2.MenuSelectedFcn = createCallbackFcn(app, @BackgorundColorMenu_2Selected, true);
            app.BackgorundColorMenu_2.Text = 'Backgorund Color';
            app.XLabelMenu_3 = uimenu(app.MultipleERPPlotMenu_2);
            app.XLabelMenu_3.MenuSelectedFcn = createCallbackFcn(app, @XLabelMenu_3Selected, true);
            app.XLabelMenu_3.Text = 'XLabel';
            app.YLabelMenu_3 = uimenu(app.MultipleERPPlotMenu_2);
            app.YLabelMenu_3.MenuSelectedFcn = createCallbackFcn(app, @YLabelMenu_3Selected, true);
            app.YLabelMenu_3.Text = 'YLabel';
            app.RESETMenu_3 = uimenu(app.MultipleERPPlotMenu_2);
            app.RESETMenu_3.MenuSelectedFcn = createCallbackFcn(app, @RESETMenu_3Selected, true);
            app.RESETMenu_3.Text = 'RESET';
            app.SaveCustomeAppearanceMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.SaveCustomeAppearanceMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveCustomeAppearanceMenuSelected, true);
            app.SaveCustomeAppearanceMenu.Text = 'Save Custome Appearance';
            app.DeleteCustomeAppearanceMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.DeleteCustomeAppearanceMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteCustomeAppearanceMenuSelected, true);
            app.DeleteCustomeAppearanceMenu.Text = 'Delete Custome Appearance';
            app.ExportDataMenu = uimenu(app.PhaseSynchroFigure);
            app.ExportDataMenu.Visible = 'off';
            app.ExportDataMenu.Enable = 'off';
            app.ExportDataMenu.Text = '   Export Data   ';
            app.ERPoverEventsMenu = uimenu(app.ExportDataMenu);
            app.ERPoverEventsMenu.Text = 'ERP over Events';
            app.matMenu = uimenu(app.ERPoverEventsMenu);
            app.matMenu.MenuSelectedFcn = createCallbackFcn(app, @matMenuSelected, true);
            app.matMenu.Text = '.mat';
            app.txtMenu = uimenu(app.ERPoverEventsMenu);
            app.txtMenu.MenuSelectedFcn = createCallbackFcn(app, @txtMenuSelected, true);
            app.txtMenu.Text = '.txt';
            app.ERPoverChannelMenu = uimenu(app.ExportDataMenu);
            app.ERPoverChannelMenu.Text = 'ERP over Channel';
            app.matMenu_2 = uimenu(app.ERPoverChannelMenu);
            app.matMenu_2.MenuSelectedFcn = createCallbackFcn(app, @matMenu_2Selected, true);
            app.matMenu_2.Text = '.mat';
            app.txtMenu_2 = uimenu(app.ERPoverChannelMenu);
            app.txtMenu_2.MenuSelectedFcn = createCallbackFcn(app, @txtMenu_2Selected, true);
            app.txtMenu_2.Text = '.txt';
            app.ExtrasMenu = uimenu(app.PhaseSynchroFigure);
            app.ExtrasMenu.Text = 'Extras';
            app.ChangeLowPassParameterMenu = uimenu(app.ExtrasMenu);
            app.ChangeLowPassParameterMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeLowPassParameterMenuSelected, true);
            app.ChangeLowPassParameterMenu.Text = 'Change Low-Pass Parameter';
            app.ShowDataAnalysedMenu = uimenu(app.ExtrasMenu);
            app.ShowDataAnalysedMenu.Text = 'Show Data Analysed';
            app.ONMenu = uimenu(app.ShowDataAnalysedMenu);
            app.ONMenu.MenuSelectedFcn = createCallbackFcn(app, @ONMenuSelected, true);
            app.ONMenu.Text = 'ON';
            app.OFFMenu = uimenu(app.ShowDataAnalysedMenu);
            app.OFFMenu.MenuSelectedFcn = createCallbackFcn(app, @OFFMenuSelected, true);
            app.OFFMenu.Text = 'OFF';
            app.ChangeFilterTypeMenu = uimenu(app.ExtrasMenu);
            app.ChangeFilterTypeMenu.Text = 'Change Filter Type';
            app.ButterworthMenu = uimenu(app.ChangeFilterTypeMenu);
            app.ButterworthMenu.MenuSelectedFcn = createCallbackFcn(app, @ButterworthMenuSelected, true);
            app.ButterworthMenu.Text = 'Butterworth';
            app.FIRMenu = uimenu(app.ChangeFilterTypeMenu);
            app.FIRMenu.MenuSelectedFcn = createCallbackFcn(app, @FIRMenuSelected, true);
            app.FIRMenu.Text = 'FIR';
            app.HowtoUseEventRelatedDataMenu = uimenu(app.ExtrasMenu);
            app.HowtoUseEventRelatedDataMenu.Text = 'How to Use Event Related Data?';
            app.ComputeResultsforERPmeanovertrialsMenu = uimenu(app.HowtoUseEventRelatedDataMenu);
            app.ComputeResultsforERPmeanovertrialsMenu.Text = 'Compute Results for ERP (mean over trials)';
            app.ComputeResultsforSupertrialalltrialsconcatonatedMenu = uimenu(app.HowtoUseEventRelatedDataMenu);
            app.ComputeResultsforSupertrialalltrialsconcatonatedMenu.Text = 'Compute Results for Supertrial (all trials concatonated)';
            app.UIAxes = uiaxes(app.PhaseSynchroFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.PhaseSynchroFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.PhaseSynchroFigure);
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.MultipleTimeRangesComparisonPanel = uipanel(app.PhaseSynchroFigure);
            app.MultipleTimeRangesComparisonPanel.Title = 'Multiple Time Ranges Comparison';
            app.CompareTimeRangesCheckBox = uicheckbox(app.MultipleTimeRangesComparisonPanel);
            app.CompareTimeRangesCheckBox.Enable = 'off';
            app.CompareTimeRangesCheckBox.Text = '   Compare Time Ranges';
            app.TimeRange1fromtoinsEditFieldLabel = uilabel(app.MultipleTimeRangesComparisonPanel);
            app.TimeRange1fromtoinsEditFieldLabel.Enable = 'off';
            app.TimeRange1fromtoinsEditFieldLabel.Text = {'Time Range 1'; '[from,to in s]'};
            app.TimeRange1fromtoinsEditField = uieditfield(app.MultipleTimeRangesComparisonPanel, 'text');
            app.TimeRange1fromtoinsEditField.Enable = 'off';
            app.TimeRange2fromtoinsEditFieldLabel = uilabel(app.MultipleTimeRangesComparisonPanel);
            app.TimeRange2fromtoinsEditFieldLabel.Enable = 'off';
            app.TimeRange2fromtoinsEditFieldLabel.Text = {'Time Range 2'; '[from,to in s]'};
            app.TimeRange2fromtoinsEditField = uieditfield(app.MultipleTimeRangesComparisonPanel, 'text');
            app.TimeRange2fromtoinsEditField.Enable = 'off';
            app.MultipleTimeRangesComparisonLabel = uilabel(app.MultipleTimeRangesComparisonPanel);
            app.MultipleTimeRangesComparisonLabel.Text = 'Multiple Time Ranges Comparison';
            app.SettingsPanel = uipanel(app.PhaseSynchroFigure);
            app.SettingsPanel.Title = 'Settings';
            app.NarrowbandCutoffLowerHigherEditFieldLabel = uilabel(app.SettingsPanel);
            app.NarrowbandCutoffLowerHigherEditFieldLabel.Text = {'Narrowband Cutoff'; '[Lower,Higher]'};
            app.NarrowbandCutoffLowerHigherEditField = uieditfield(app.SettingsPanel, 'text');
            app.NarrowbandCutoffLowerHigherEditField.ValueChangedFcn = createCallbackFcn(app, @NarrowbandCutoffLowerHigherEditFieldValueChanged, true);
            app.NarrowbandCutoffLowerHigherEditField.Value = '12,16';
            app.NarrowbandFilterorderEditFieldLabel = uilabel(app.SettingsPanel);
            app.NarrowbandFilterorderEditFieldLabel.Text = {'Narrowband'; 'Filterorder'};
            app.NarrowbandFilterorderEditField = uieditfield(app.SettingsPanel, 'text');
            app.NarrowbandFilterorderEditField.ValueChangedFcn = createCallbackFcn(app, @NarrowbandFilterorderEditFieldValueChanged, true);
            app.NarrowbandFilterorderEditField.Value = '4';
            app.ForceFilterOFFCheckBox = uicheckbox(app.SettingsPanel);
            app.ForceFilterOFFCheckBox.ValueChangedFcn = createCallbackFcn(app, @ForceFilterOFFCheckBoxValueChanged, true);
            app.ForceFilterOFFCheckBox.Text = '   Force Filter OFF';
            app.ECHTFilterorderEditField = uieditfield(app.SettingsPanel, 'text');
            app.ECHTFilterorderEditField.ValueChangedFcn = createCallbackFcn(app, @ECHTFilterorderEditFieldValueChanged, true);
            app.ECHTFilterorderEditField.Value = '2';
            app.ECHTFilterorderLabel = uilabel(app.SettingsPanel);
            app.ECHTFilterorderLabel.Text = {'ECHT'; 'Filterorder'};
            app.DataToExtractFromDropDownLabel = uilabel(app.SettingsPanel);
            app.DataToExtractFromDropDownLabel.Text = 'Data To Extract From';
            app.DataToExtractFromDropDown = uidropdown(app.SettingsPanel);
            app.DataToExtractFromDropDown.Items = {''};
            app.DataToExtractFromDropDown.ValueChangedFcn = createCallbackFcn(app, @DataToExtractFromDropDownValueChanged, true);
            app.DataToExtractFromDropDown.Value = '';
            app.EventChannelSelectionDropDownLabel = uilabel(app.SettingsPanel);
            app.EventChannelSelectionDropDownLabel.Text = 'Event Channel Selection';
            app.EventChannelSelectionDropDown = uidropdown(app.SettingsPanel);
            app.EventChannelSelectionDropDown.Items = {};
            app.EventChannelSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @EventChannelSelectionDropDownValueChanged, true);
            app.EventChannelSelectionDropDown.Value = {};
            app.EventDataTypeDropDownLabel = uilabel(app.SettingsPanel);
            app.EventDataTypeDropDownLabel.Text = 'Event Data Type';
            app.DataTypeDropDown = uidropdown(app.SettingsPanel);
            app.DataTypeDropDown.Items = {'Raw Data'};
            app.DataTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DataTypeDropDownValueChanged2, true);
            app.DataTypeDropDown.Value = 'Raw Data';
            app.TrialSelectionMatlabExpressionsEditFieldLabel = uilabel(app.SettingsPanel);
            app.TrialSelectionMatlabExpressionsEditFieldLabel.Text = {'Trial Selection'; '(Matlab Expressions)'};
            app.TrialSelectionMatlabExpressionsEditField = uieditfield(app.SettingsPanel, 'text');
            app.TrialSelectionMatlabExpressionsEditField.ValueChangedFcn = createCallbackFcn(app, @TrialSelectionMatlabExpressionsEditFieldValueChanged, true);
            app.CalculationMethodDropDownLabel = uilabel(app.SettingsPanel);
            app.CalculationMethodDropDownLabel.Text = 'Calculation Method';
            app.CalculationMethodDropDown = uidropdown(app.SettingsPanel);
            app.CalculationMethodDropDown.Items = {'Hilbert Transform', 'Endpoint Corrected Hilbert Transform', 'Wavelet Convolution'};
            app.CalculationMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @CalculationMethodDropDownValueChanged, true);
            app.CalculationMethodDropDown.Value = 'Hilbert Transform';
            app.SettingsLabel = uilabel(app.SettingsPanel);
            app.SettingsLabel.Text = 'Settings';
            app.BaselineNormalizeCheckBox = uicheckbox(app.SettingsPanel);
            app.BaselineNormalizeCheckBox.ValueChangedFcn = createCallbackFcn(app, @BaselineNormalizeCheckBoxValueChanged, true);
            app.BaselineNormalizeCheckBox.Text = {'   Baseline '; '   Normalize'};
            app.TriggerNumberSelectionMatlabExpressionsLabel = uilabel(app.SettingsPanel);
            app.TriggerNumberSelectionMatlabExpressionsLabel.Text = {'Baseline Window'; '[from, to] in s'};
            app.EventNumberSelectionEditField = uieditfield(app.SettingsPanel, 'text');
            app.EventNumberSelectionEditField.ValueChangedFcn = createCallbackFcn(app, @EventNumberSelectionEditFieldValueChanged, true);
            app.EventNumberSelectionEditField_2 = uieditfield(app.SettingsPanel, 'text');
            app.CloseButton = uibutton(app.PhaseSynchroFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed2, true);
            app.CloseButton.Text = 'Close';
            app.Panel = uipanel(app.PhaseSynchroFigure);
            app.TextArea = uitextarea(app.PhaseSynchroFigure);
            app.ChannelSelectionDropDown = uidropdown(app.PhaseSynchroFigure);
            app.ChannelSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelSelectionDropDownValueChanged, true);
            app.vsLabel = uilabel(app.PhaseSynchroFigure);
            app.vsLabel.Text = 'vs';
            app.ChannelSelectionDropDown_2Label = uilabel(app.PhaseSynchroFigure);
            app.ChannelSelectionDropDown_2Label.Text = 'Channel Selection';
            app.ChannelSelectionDropDown_2 = uidropdown(app.PhaseSynchroFigure);
            app.ChannelSelectionDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ChannelSelectionDropDown_2ValueChanged, true);
            app.ContextMenu4 = uicontextmenu(app.PhaseSynchroFigure);
            app.ChangeSpacingLimitsMenu = uimenu(app.ContextMenu4);
            app.ChangeSpacingLimitsMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeSpacingLimitsMenuSelected, true);
            app.ChangeSpacingLimitsMenu.Text = 'Change Spacing Limits';
            % Show the figure after all components are created
            app.PhaseSynchroFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Event_PhaseSynchro_Analysis_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.PhaseSynchroFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.PhaseSynchroFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PhaseSynchroFigure)
        end
    end
end