classdef Event_Static_Power_Spectrum_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EventStaticSpectrumWindowUIFigure  matlab.ui.Figure
        SnapshotMenu                    matlab.ui.container.Menu
        SaveSnapshotofWindowMenu        matlab.ui.container.Menu
        PNGMenu                         matlab.ui.container.Menu
        JPGMenu                         matlab.ui.container.Menu
        SVGMenu                         matlab.ui.container.Menu
        LeftPlotMenu                    matlab.ui.container.Menu
        RightPlotMenu                   matlab.ui.container.Menu
        EPSMenu                         matlab.ui.container.Menu
        LeftPlotMenu_2                  matlab.ui.container.Menu
        RightPlotMenu_2                 matlab.ui.container.Menu
        PlotMenu_2                      matlab.ui.container.Menu
        ChangePlotTypeMenu              matlab.ui.container.Menu
        IndividualChannelViewMenu       matlab.ui.container.Menu
        DepthViewMenu                   matlab.ui.container.Menu
        Show2DimensionalPlotMenu        matlab.ui.container.Menu
        Show3DimensionalPlotMenu        matlab.ui.container.Menu
        GridTraceViewMenu               matlab.ui.container.Menu
        LinesMenu                       matlab.ui.container.Menu
        ColorplotMenu                   matlab.ui.container.Menu
        ChangePlotPropertiesMenu        matlab.ui.container.Menu
        DataPlotMenu                    matlab.ui.container.Menu
        Menu                            matlab.ui.container.Menu
        FontSizeMenu                    matlab.ui.container.Menu
        LineWidthDataMenu               matlab.ui.container.Menu
        ColorDataMenu                   matlab.ui.container.Menu
        BackgorundColorMenu             matlab.ui.container.Menu
        XLabelMenu                      matlab.ui.container.Menu
        YLabelMenu                      matlab.ui.container.Menu
        RESETMenu                       matlab.ui.container.Menu
        GridViewPlotMenu                matlab.ui.container.Menu
        LineWidthDataMenu_2             matlab.ui.container.Menu
        ColorDataMenu_2                 matlab.ui.container.Menu
        RESETMenu_2                     matlab.ui.container.Menu
        SaveCustomeAppearanceMenu       matlab.ui.container.Menu
        DeleteCustomeAppearanceMenu     matlab.ui.container.Menu
        ExportDataMenu                  matlab.ui.container.Menu
        SpectrumIndividualChannelMenu   matlab.ui.container.Menu
        matMenu_3                       matlab.ui.container.Menu
        txtMenu_3                       matlab.ui.container.Menu
        xlsxMenu_3                      matlab.ui.container.Menu
        GridERPMenu                     matlab.ui.container.Menu
        matMenu_2                       matlab.ui.container.Menu
        txtMenu_2                       matlab.ui.container.Menu
        xlsxMenu_2                      matlab.ui.container.Menu
        Button_2                        matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        CloseButton                     matlab.ui.control.Button
        TextArea_2                      matlab.ui.control.TextArea
        AnalysisSettingsPanel           matlab.ui.container.Panel
        FrequencyRangeHzEditField       matlab.ui.control.EditField
        FrequencyRangeHzfromtoEditFieldLabel  matlab.ui.control.Label
        ChannelDropDown                 matlab.ui.control.DropDown
        ChannelSelectionDropDownLabel   matlab.ui.control.Label
        TriggerNumberSelectionMatlabExpressionsLabel_2  matlab.ui.control.Label
        EventNumberSelectionEditField_2  matlab.ui.control.EditField
        EventNumberSelectionEditField   matlab.ui.control.EditField
        TriggerNumberSelectionMatlabExpressionsLabel  matlab.ui.control.Label
        BaselineNormalizeCheckBox       matlab.ui.control.CheckBox
        DataSourceDropDown              matlab.ui.control.DropDown
        EventDataTypeLabel              matlab.ui.control.Label
        DataToExtractFromDropDown       matlab.ui.control.DropDown
        DataToExtractFromDropDownLabel  matlab.ui.control.Label
        EventTriggerChannel             matlab.ui.control.DropDown
        EventChannelSelectionDropDownLabel  matlab.ui.control.Label
        AnalysisSettingsLabel           matlab.ui.control.Label
        EventSelectionEditField         matlab.ui.control.EditField
        TriggerNumberSelectionLabel     matlab.ui.control.Label
        DataTypeDropDown                matlab.ui.control.DropDown
        MethodLabel                     matlab.ui.control.Label
        Grid_Traces_View_Panel          matlab.ui.container.Panel
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Mainapp = []; % Description
        BandPower = []
        TwoORThreeD = "TwoD"; % standard 2d plots when possible
        TextArea = [];
        EventTime = [];
        TimearoundEvent = [];
        SelectedChannel = [];
        SelectedEvents = [];

        ChannelGrid = [];
        ChannelAxes = [];

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            %% Initiate GUI 
            app.Mainapp = Mainapp;
            
            app = Utility_Change_Light_Dark_Mode(app,"EventSpectrum");

            movegui(app.EventStaticSpectrumWindowUIFigure, 'center');
            
            app.Grid_Traces_View_Panel.Enable = "off";
            app.Grid_Traces_View_Panel.Visible = "off";

            % if str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)<=2 && strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Across Grid")
            %     warning("Plotappearance was set to display Band Power Across Grid, which is not possible with less or equal then 2 channel rows. Auto-changing to Individual Channel View.")
            %     app.Mainapp.PlotAppearance.SpectrumWindow.PlotType = "Band Power Individual Channel ";
            % end

            %% -------------------- Set UP Window General things-------------------- 

            if isfield(app.Mainapp.Data,'Preprocessed')
                app.DataToExtractFromDropDown.Items{1} = 'Raw Data';
                app.DataToExtractFromDropDown.Items{2} = 'Preprocessed Data';
            else
                app.DataToExtractFromDropDown.Items{1} = 'Raw Data';
            end
            
            for i = 1:length(app.Mainapp.Data.Info.EventChannelNames)
                app.EventTriggerChannel.Items{i} = convertStringsToChars(app.Mainapp.Data.Info.EventChannelNames{i});
            end

            %% -------------------- Check if prepro event related data found and can be offered -------------------- 
            [texttoshow,Value] = Event_Module_Check_Event_Preprocessing(app.Mainapp.Data,app.EventTriggerChannel.Value,"Raw Event Related Data");
            app.DataSourceDropDown.Items = {};
            app.DataSourceDropDown.Items = texttoshow;
            app.DataSourceDropDown.Value = Value;
            
            %% -------------------- setup time --------------------
            spaceindicie = strfind(app.Mainapp.Data.Info.EventRelatedDataTimeRange," ");
            app.TimearoundEvent(1) = str2double(app.Mainapp.Data.Info.EventRelatedDataTimeRange(1:spaceindicie(1)-1));
            app.TimearoundEvent(2) = str2double(app.Mainapp.Data.Info.EventRelatedDataTimeRange(spaceindicie(1)+1:end));
            
            %% -------------------- Extract Event Related Data -------------------- 
            [app.Mainapp.Data,~] = Event_Module_Extract_Event_Related_Data(app.Mainapp.Data,app.EventTriggerChannel.Value,app.TimearoundEvent,app.DataToExtractFromDropDown.Value,app.DataSourceDropDown.Value);

            %% -------------------- Set Basline Normlaization and trigger nhumber -------------------- 
            EventRange = str2double(strsplit(app.Mainapp.Data.Info.EventRelatedDataTimeRange,' '));
            app.EventNumberSelectionEditField.Value = strcat('-',num2str(EventRange(1)),',0');
            
            if strcmp(app.DataSourceDropDown.Value,'Raw Event Related Data')
                app.EventNumberSelectionEditField_2.Value = strcat(num2str(size(app.Mainapp.Data.EventRelatedData,2)),' Trigger');
            else
                app.EventNumberSelectionEditField_2.Value = strcat(num2str(size(app.Mainapp.Data.PreprocessedEventRelatedData,2)),' Trigger');
            end

            %% -------------------- Populate event channel selection -------------------- 
            [app.EventSelectionEditField.Value] = Event_Module_Check_EventInput(app.EventSelectionEditField.Value,app.Mainapp.Data,app.EventTriggerChannel.Value,app.DataSourceDropDown.Value,1);
            
            app.SelectedEvents = eval(app.EventSelectionEditField.Value);

             %% -------------------- Set up channel -------------------- 
            
            AllChannels = {};
            if isfield(app.Mainapp.Data,'Raw')
                N = size(app.Mainapp.Data.Raw,1);  
            elseif isfield(app.Mainapp.Data,'Preprocessed')
                N = size(app.Mainapp.Data.Preprocessed,1);  
            end
            
            if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 1
                TempActiveChannel = (str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)*str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)+1)-sort(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
                for i = 1:N
                    AllChannels{i} = num2str(TempActiveChannel(i));
                end
            else
                for i = 1:N
                    AllChannels{i} = num2str(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel(i));
                end
            end

            app.ChannelDropDown.Items = AllChannels;

            TempSelectedChannel = str2double(app.ChannelDropDown.Value);              

            if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 1
                TempActiveChannel = (str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)*str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)+1)-sort(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
                [app.SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(TempActiveChannel,TempSelectedChannel,'MainWindow');
            else
                [app.SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,TempSelectedChannel,'MainWindow');
            end
            
            %% -------------------- Plot -------------------- 
            if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Individual Channel ")
                app.Grid_Traces_View_Panel.Visible = "off";
                app.Grid_Traces_View_Panel.Enable = "off";
                app.Button_2.Visible = "on";
                app.Button_2.Enable = "on";

                if ~isempty(app.ChannelAxes)
                    for k = 1:numel(app.ChannelAxes)
                        if isvalid(app.ChannelAxes{k})  
                            delete(app.ChannelAxes{k});
                        end
                    end
                    app.ChannelAxes = [];
                end
                if ~isempty(app.ChannelGrid)
                    delete(app.ChannelGrid)
                end

                app.ChannelDropDown.Enable = "on";
                app.DataTypeDropDown.Enable = "on";
                app.FrequencyRangeHzEditField.Enable = "on";

                set(app.UIAxes, 'YDir', 'normal');
                cb = colorbar(app.UIAxes);   % Create a colorbar (if it exists)
                if ~isempty(cb)
                    delete(cb);                  % Delete the colorbar
                end
                
                [app.Mainapp.CurrentPlotData] = Event_Analyse_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,app.SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.SelectedEvents,app.DataToExtractFromDropDown.Value,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
                
                [~,app.BandPower,app.Mainapp.CurrentPlotData] = Event_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,"Just Frequency Bands",app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SelectedEvents,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.DataToExtractFromDropDown.Value,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);

            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power over Depth")
                app.Grid_Traces_View_Panel.Visible = "off";
                app.Grid_Traces_View_Panel.Enable = "off";
                app.Button_2.Visible = "on";
                app.Button_2.Enable = "on";

                if ~isempty(app.ChannelAxes)
                    for k = 1:numel(app.ChannelAxes)
                        if isvalid(app.ChannelAxes{k})  
                            delete(app.ChannelAxes{k});
                        end
                    end
                    app.ChannelAxes = [];
                end
                if ~isempty(app.ChannelGrid)
                    delete(app.ChannelGrid)
                end

                app.ChannelDropDown.Enable = "off";
                app.DataTypeDropDown.Enable = "off";
                app.FrequencyRangeHzEditField.Enable = "on";

                %app.UIAxes.YScale = 'linear';
                
                [~,app.BandPower,app.Mainapp.CurrentPlotData] = Event_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'All',app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SelectedEvents,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.DataToExtractFromDropDown.Value,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);

                cb = colorbar(app.UIAxes);
                cb.Color = 'k';              % Sets tick mark and label color to black
                cb.Label.String = "Power [dB]";
                cb.Label.Rotation = 270;
                %cb.FontSize =  app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeFontSize;
                cb.Label.Color = 'k';        % Sets the color of the label text
            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Across Grid")

                % if str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)<=2
                %     msgbox("At least 3 channel rows required! Returning");
                %     return;
                % end
                
                app.Grid_Traces_View_Panel.Visible = "on";
                app.Grid_Traces_View_Panel.Enable = "on";
                app.Button_2.Visible = "off";
                app.Button_2.Enable = "off";

                app.ChannelDropDown.Enable = "off";
                app.DataTypeDropDown.Enable = "off";
                app.FrequencyRangeHzEditField.Enable = "on";
                
                [~,app.BandPower,app.Mainapp.CurrentPlotData] = Event_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,"Just Frequency Bands",app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SelectedEvents,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.DataToExtractFromDropDown.Value,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);

                if isempty(app.ChannelAxes)
                    app = Module_MainWindow_Initialize_Grid_Trace_Panel(app,app.Mainapp,"StaticEventSpectrum");
                end         
                
                Freqs = str2double(strsplit(app.FrequencyRangeHzEditField.Value,','));
                [~,FreqsIndicie(1)] = min(abs(app.BandPower.F - Freqs(1)));
                [~,FreqsIndicie(2)] = min(abs(app.BandPower.F - Freqs(2)));

                EventRelatedSpectrum = app.BandPower.allPowerEst(:,FreqsIndicie(1):FreqsIndicie(2));
    
                [Channelrange] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.Mainapp.ActiveChannel,'MainPlot');
                EventRelatedSpectrum = EventRelatedSpectrum(Channelrange,:);
                
                Ylims = [min(EventRelatedSpectrum,[],'all') max(EventRelatedSpectrum,[],'all')];

                % EventRelatedData is channel by time
                [~,app.Mainapp.CurrentPlotData] = Module_Main_Window_Plot_Grid_Trace_View(app,app.Mainapp.Data,EventRelatedSpectrum,app.BandPower.F(FreqsIndicie(1):FreqsIndicie(2))',app.BaselineNormalizeCheckBox.Value,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations,[],app.Mainapp.PlotAppearance.SpectrumWindow.GridPlotType,0,Ylims,app.Mainapp.CurrentPlotData);
  
            end

            app.UIAxes.Color = app.Mainapp.PlotAppearance.SpectrumWindow.Data.SpectrumBackgroundColor;
            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeFontSize;
            
            app = Utility_Change_Light_Dark_Mode(app,"EventSpectrum");
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            try
                app.Mainapp.EventLFPSSP = [];
    
                if ~isempty(app.Mainapp.ProbeViewWindowHandle) % delete option from probe view 
                    for i = 1:length(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                        if strcmp(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Event Static Spectrum Window')
                            app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items(i) = [];
                            break;
                        end
                    end
                else % if no probe view available
     
                end
            catch

            end

            delete(app);
        end

        % Value changed function: DataTypeDropDown
        function DataTypeDropDownValueChanged(app, event)
            if strcmp(app.DataTypeDropDown.Value,"Mean over all Channel")
                app.ChannelDropDown.Enable = "off";
            else
                app.ChannelDropDown.Enable = "on";
                set(app.UIAxes, 'YDir', 'normal');
            end
            
            %% -------------------- PLOT -------------------- 
            [app.Mainapp.CurrentPlotData] = Event_Analyse_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,app.SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.SelectedEvents,app.DataToExtractFromDropDown.Value,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
        end

        % Value changed function: ChannelDropDown
        function ChannelDropDownValueChanged(app, event)
            %% -------------------- Set Channel -------------------- 
            TempSelectedChannel = str2double(app.ChannelDropDown.Value);              

            if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 1
                TempActiveChannel = (str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)*str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)+1)-sort(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
                [app.SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(TempActiveChannel,TempSelectedChannel,'MainWindow');
            else
                [app.SelectedChannel] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,TempSelectedChannel,'MainWindow');
            end
            
            %% -------------------- PLOT -------------------- 
            [app.Mainapp.CurrentPlotData] = Event_Analyse_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,app.SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.SelectedEvents,app.DataToExtractFromDropDown.Value,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
        end

        % Value changed function: DataSourceDropDown
        function DataSourceDropDownValueChanged(app, event)
            app.UIAxes_2.NextPlot = "replace";
            cla(app.UIAxes_2)
            app.UIAxes_2.NextPlot = "add";
            
            %% -------------------- Extract Event Related Data -------------------- 
            [app.Mainapp.Data,~] = Event_Module_Extract_Event_Related_Data(app.Mainapp.Data,app.EventTriggerChannel.Value,app.TimearoundEvent,app.DataToExtractFromDropDown.Value,app.DataSourceDropDown.Value);
            
            %% -------------------- Set Basline Normlaization and trigger number -------------------- 
            if strcmp(app.DataSourceDropDown.Value,'Raw Event Related Data')
                app.EventNumberSelectionEditField_2.Value = strcat(num2str(size(app.Mainapp.Data.EventRelatedData,2)),' Trigger');
            else
                app.EventNumberSelectionEditField_2.Value = strcat(num2str(size(app.Mainapp.Data.PreprocessedEventRelatedData,2)),' Trigger');
            end

            %% -------------------- Populate event channel selection -------------------- 
            [app.EventSelectionEditField.Value] = Event_Module_Check_EventInput(app.EventSelectionEditField.Value,app.Mainapp.Data,app.EventTriggerChannel.Value,app.DataSourceDropDown.Value,1);
            
            app.SelectedEvents = eval(app.EventSelectionEditField.Value);
            
            %% -------------------- Plot -------------------- 
            if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Individual Channel ")
                app.ChannelDropDown.Enable = "on";
                app.DataTypeDropDown.Enable = "on";

                [app.Mainapp.CurrentPlotData] = Event_Analyse_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,app.SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.SelectedEvents,app.DataToExtractFromDropDown.Value,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);

                [~,app.BandPower,app.Mainapp.CurrentPlotData] = Event_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'Just Frequency Bands',app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SelectedEvents,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.DataToExtractFromDropDown.Value,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
            
            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power over Depth")
                app.ChannelDropDown.Enable = "off";
                app.DataTypeDropDown.Enable = "off";

                [~,app.BandPower,app.Mainapp.CurrentPlotData] = Event_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'All',app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SelectedEvents,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.DataToExtractFromDropDown.Value,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
                
                cb = colorbar(app.UIAxes);
                cb.Color = 'k';              % Sets tick mark and label color to black
                cb.Label.String = "Power [dB]";
                cb.Label.Rotation = 270;
                %cb.FontSize =  app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeFontSize;
                cb.Label.Color = 'k';        % Sets the color of the label text
            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Across Grid")  
                
                [~,app.BandPower,app.Mainapp.CurrentPlotData] = Event_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,"Just Frequency Bands",app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SelectedEvents,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.DataToExtractFromDropDown.Value,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
                
                Freqs = str2double(strsplit(app.FrequencyRangeHzEditField.Value,','));
                [~,FreqsIndicie(1)] = min(abs(app.BandPower.F - Freqs(1)));
                [~,FreqsIndicie(2)] = min(abs(app.BandPower.F - Freqs(2)));

                EventRelatedSpectrum = app.BandPower.allPowerEst(:,FreqsIndicie(1):FreqsIndicie(2));
    
                [Channelrange] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.Mainapp.ActiveChannel,'MainPlot');
                EventRelatedSpectrum = EventRelatedSpectrum(Channelrange,:);
                
                Ylims = [min(EventRelatedSpectrum,[],'all') max(EventRelatedSpectrum,[],'all')];

                % EventRelatedData is channel by time
                [~,app.Mainapp.CurrentPlotData] = Module_Main_Window_Plot_Grid_Trace_View(app,app.Mainapp.Data,EventRelatedSpectrum,app.BandPower.F(FreqsIndicie(1):FreqsIndicie(2))',app.BaselineNormalizeCheckBox.Value,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations,[],app.Mainapp.PlotAppearance.SpectrumWindow.GridPlotType,0,Ylims,app.Mainapp.CurrentPlotData);
  
            end
        end

        % Menu selected function: PNGMenu
        function PNGMenuSelected(app, event)
            [file, path] = uiputfile('*.png', 'Save as');

            exportapp(app.EventStaticSpectrumWindowUIFigure,fullfile(path,file)) 
        end

        % Menu selected function: JPGMenu
        function JPGMenuSelected(app, event)
            [file, path] = uiputfile('*.jpg', 'Save as');

            exportapp(app.EventStaticSpectrumWindowUIFigure,fullfile(path,file)) 
        end

        % Value changed function: FrequencyRangeHzEditField
        function FrequencyRangeHzEditFieldValueChanged(app, event)
            [app.FrequencyRangeHzEditField.Value] = Utility_SimpleCheckInputs(app.FrequencyRangeHzEditField.Value,"Two",'0,100',0,0);
            
            %% -------------------- Plot -------------------- 

            if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Individual Channel ")
                app.ChannelDropDown.Enable = "on";
                app.DataTypeDropDown.Enable = "on";
                app.FrequencyRangeHzEditField.Enable = "on";

                set(app.UIAxes, 'YDir', 'normal');

                [app.Mainapp.CurrentPlotData] = Event_Analyse_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,app.SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.SelectedEvents,app.DataToExtractFromDropDown.Value,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);

            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power over Depth")
                app.ChannelDropDown.Enable = "off";
                app.DataTypeDropDown.Enable = "off";
                app.FrequencyRangeHzEditField.Enable = "on";
                
                [~,app.BandPower,app.Mainapp.CurrentPlotData] = Event_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'All',app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SelectedEvents,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.DataToExtractFromDropDown.Value,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
                
                cb = colorbar(app.UIAxes);
                cb.Color = 'k';              % Sets tick mark and label color to black
                cb.Label.String = "Power [dB]";
                cb.Label.Rotation = 270;
                %cb.FontSize =  app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeFontSize;
                cb.Label.Color = 'k';        % Sets the color of the label text
            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Across Grid")
                Freqs = str2double(strsplit(app.FrequencyRangeHzEditField.Value,','));
                [~,FreqsIndicie(1)] = min(abs(app.BandPower.F - Freqs(1)));
                [~,FreqsIndicie(2)] = min(abs(app.BandPower.F - Freqs(2)));

                EventRelatedSpectrum = app.BandPower.allPowerEst(:,FreqsIndicie(1):FreqsIndicie(2));
    
                [Channelrange] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.Mainapp.ActiveChannel,'MainPlot');
                EventRelatedSpectrum = EventRelatedSpectrum(Channelrange,:);
                
                Ylims = [min(EventRelatedSpectrum,[],'all') max(EventRelatedSpectrum,[],'all')];

                % EventRelatedData is channel by time
                [~,app.Mainapp.CurrentPlotData] = Module_Main_Window_Plot_Grid_Trace_View(app,app.Mainapp.Data,EventRelatedSpectrum,app.BandPower.F(FreqsIndicie(1):FreqsIndicie(2))',app.BaselineNormalizeCheckBox.Value,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations,[],app.Mainapp.PlotAppearance.SpectrumWindow.GridPlotType,0,Ylims,app.Mainapp.CurrentPlotData);
  

            end

        end

        % Menu selected function: Show2DimensionalPlotMenu
        function Show2DimensionalPlotMenuSelected(app, event)
            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";

            if ~isempty(app.ChannelAxes)
                for k = 1:numel(app.ChannelAxes)
                    if isvalid(app.ChannelAxes{k})  
                        delete(app.ChannelAxes{k});
                    end
                end
                app.ChannelAxes = [];
            end
            if ~isempty(app.ChannelGrid)
                delete(app.ChannelGrid)
            end

            app.Grid_Traces_View_Panel.Visible = "off";
            app.Grid_Traces_View_Panel.Enable = "off";
            app.Button_2.Visible = "on";
            app.Button_2.Enable = "on";
            
            app.Mainapp.PlotAppearance.SpectrumWindow.PlotType = "Band Power over Depth";

            app.UIAxes.Color = app.Mainapp.PlotAppearance.SpectrumWindow.Data.SpectrumBackgroundColor;
            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeFontSize;

            app.TwoORThreeD = "TwoD";
            
            %% -------------------- Plot -------------------- 

            if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Individual Channel ")
                app.ChannelDropDown.Enable = "on";
                app.DataTypeDropDown.Enable = "on";

                set(app.UIAxes, 'YDir', 'normal');

                [app.Mainapp.CurrentPlotData] = Event_Analyse_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,app.SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.SelectedEvents,app.DataToExtractFromDropDown.Value,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);

            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power over Depth")
                app.ChannelDropDown.Enable = "off";
                app.DataTypeDropDown.Enable = "off";
                app.FrequencyRangeHzEditField.Enable = "on";
                
                [~,app.BandPower,app.Mainapp.CurrentPlotData] = Event_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'All',app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SelectedEvents,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.DataToExtractFromDropDown.Value,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
            
            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Across Grid")  

                Freqs = str2double(strsplit(app.FrequencyRangeHzEditField.Value,','));
                [~,FreqsIndicie(1)] = min(abs(app.BandPower.F - Freqs(1)));
                [~,FreqsIndicie(2)] = min(abs(app.BandPower.F - Freqs(2)));

                EventRelatedSpectrum = app.BandPower.allPowerEst(:,FreqsIndicie(1):FreqsIndicie(2));
    
                [Channelrange] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.Mainapp.ActiveChannel,'MainPlot');
                EventRelatedSpectrum = EventRelatedSpectrum(Channelrange,:);
                
                Ylims = [min(EventRelatedSpectrum,[],'all') max(EventRelatedSpectrum,[],'all')];

                % EventRelatedData is channel by time
                [~,app.Mainapp.CurrentPlotData] = Module_Main_Window_Plot_Grid_Trace_View(app,app.Mainapp.Data,EventRelatedSpectrum,app.BandPower.F(FreqsIndicie(1):FreqsIndicie(2))',app.BaselineNormalizeCheckBox.Value,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations,[],app.Mainapp.PlotAppearance.SpectrumWindow.GridPlotType,0,Ylims,app.Mainapp.CurrentPlotData);
  
            end
        end

        % Menu selected function: matMenu_3
        function matMenuSelected(app, event)
            TimeRangePlot = str2double(app.Mainapp.TimeRangeViewBox.Value(1:end-1));
            
            if strcmp(app.Mainapp.DropDown.Value,"Preprocessed")
                if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                else
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                end
            else
                StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
            end

            if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Individual Channel ")
                Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Event Related Static Spectrum Individual Channel",0);
            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power over Depth")
                Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Event Related Static Spectrum over Depth",0);
            end
        end

        % Menu selected function: txtMenu_3
        function txtMenuSelected(app, event)
            TimeRangePlot = str2double(app.Mainapp.TimeRangeViewBox.Value(1:end-1));
            if strcmp(app.Mainapp.DropDown.Value,"Preprocessed")
                if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                else
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                end
            else
                StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
            end

            if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Individual Channel ")
                Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Event Related Static Spectrum Individual Channel",0);
            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power over Depth")
                Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Event Related Static Spectrum over Depth",0);
            end
        end

        % Menu selected function: Show3DimensionalPlotMenu
        function Show3DimensionalPlotMenuSelected(app, event)
            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";
            
            if ~isempty(app.ChannelAxes)
                for k = 1:numel(app.ChannelAxes)
                    if isvalid(app.ChannelAxes{k})  
                        delete(app.ChannelAxes{k});
                    end
                end
                app.ChannelAxes = [];
            end
            if ~isempty(app.ChannelGrid)
                delete(app.ChannelGrid)
            end
            
            app.Grid_Traces_View_Panel.Visible = "off";
            app.Grid_Traces_View_Panel.Enable = "off";
            app.Button_2.Visible = "on";
            app.Button_2.Enable = "on";

            app.Mainapp.PlotAppearance.SpectrumWindow.PlotType = "Band Power over Depth";
            
            app.UIAxes.Color = app.Mainapp.PlotAppearance.SpectrumWindow.Data.SpectrumBackgroundColor;
            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeFontSize;

            app.TwoORThreeD = "ThreeD";
            
            %% -------------------- Plot -------------------- 
            if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Individual Channel ")
                app.ChannelDropDown.Enable = "on";
                app.DataTypeDropDown.Enable = "on";

                set(app.UIAxes, 'YDir', 'normal');

                [app.Mainapp.CurrentPlotData] = Event_Analyse_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,app.SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.SelectedEvents,app.DataToExtractFromDropDown.Value,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);

            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power over Depth")
                app.ChannelDropDown.Enable = "off";
                app.DataTypeDropDown.Enable = "off";
                app.FrequencyRangeHzEditField.Enable = "on";
                
                [~,app.BandPower,app.Mainapp.CurrentPlotData] = Event_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'All',app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SelectedEvents,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.DataToExtractFromDropDown.Value,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
            end
        end

        % Menu selected function: FontSizeMenu
        function FontSizeMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'fontsize',app.Mainapp,"SpectrumFontSize");
            waitfor(h);
            %% -------------------- Plot -------------------- 

            if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Individual Channel ")
                app.ChannelDropDown.Enable = "on";
                app.DataTypeDropDown.Enable = "on";

                set(app.UIAxes, 'YDir', 'normal');

                [app.Mainapp.CurrentPlotData] = Event_Analyse_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,app.SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.SelectedEvents,app.DataToExtractFromDropDown.Value,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);

            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power over Depth")
                app.ChannelDropDown.Enable = "off";
                app.DataTypeDropDown.Enable = "off";
                app.FrequencyRangeHzEditField.Enable = "on";
                
                [~,app.BandPower,app.Mainapp.CurrentPlotData] = Event_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'All',app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SelectedEvents,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.DataToExtractFromDropDown.Value,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
            
            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Across Grid")  

                Freqs = str2double(strsplit(app.FrequencyRangeHzEditField.Value,','));
                [~,FreqsIndicie(1)] = min(abs(app.BandPower.F - Freqs(1)));
                [~,FreqsIndicie(2)] = min(abs(app.BandPower.F - Freqs(2)));

                EventRelatedSpectrum = app.BandPower.allPowerEst(:,FreqsIndicie(1):FreqsIndicie(2));
    
                [Channelrange] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.Mainapp.ActiveChannel,'MainPlot');
                EventRelatedSpectrum = EventRelatedSpectrum(Channelrange,:);
                
                Ylims = [min(EventRelatedSpectrum,[],'all') max(EventRelatedSpectrum,[],'all')];

                % EventRelatedData is channel by time
                [~,app.Mainapp.CurrentPlotData] = Module_Main_Window_Plot_Grid_Trace_View(app,app.Mainapp.Data,EventRelatedSpectrum,app.BandPower.F(FreqsIndicie(1):FreqsIndicie(2))',app.BaselineNormalizeCheckBox.Value,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations,[],app.Mainapp.PlotAppearance.SpectrumWindow.GridPlotType,0,Ylims,app.Mainapp.CurrentPlotData);
  
            end

            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeFontSize;
        end

        % Menu selected function: LineWidthDataMenu
        function LineWidthDataMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'linewidth',app.Mainapp,"SpectrumLineWidth");
            waitfor(h);
            %% -------------------- Plot -------------------- 

            if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Individual Channel ")
                app.ChannelDropDown.Enable = "on";
                app.DataTypeDropDown.Enable = "on";

                set(app.UIAxes, 'YDir', 'normal');

                [app.Mainapp.CurrentPlotData] = Event_Analyse_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,app.SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.SelectedEvents,app.DataToExtractFromDropDown.Value,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);

            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power over Depth")
                app.ChannelDropDown.Enable = "off";
                app.DataTypeDropDown.Enable = "off";
                app.FrequencyRangeHzEditField.Enable = "on";
                
                [~,app.BandPower,app.Mainapp.CurrentPlotData] = Event_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'All',app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SelectedEvents,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.DataToExtractFromDropDown.Value,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
            
            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Across Grid")  

                Freqs = str2double(strsplit(app.FrequencyRangeHzEditField.Value,','));
                [~,FreqsIndicie(1)] = min(abs(app.BandPower.F - Freqs(1)));
                [~,FreqsIndicie(2)] = min(abs(app.BandPower.F - Freqs(2)));

                EventRelatedSpectrum = app.BandPower.allPowerEst(:,FreqsIndicie(1):FreqsIndicie(2));
    
                [Channelrange] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.Mainapp.ActiveChannel,'MainPlot');
                EventRelatedSpectrum = EventRelatedSpectrum(Channelrange,:);
                
                Ylims = [min(EventRelatedSpectrum,[],'all') max(EventRelatedSpectrum,[],'all')];

                % EventRelatedData is channel by time
                [~,app.Mainapp.CurrentPlotData] = Module_Main_Window_Plot_Grid_Trace_View(app,app.Mainapp.Data,EventRelatedSpectrum,app.BandPower.F(FreqsIndicie(1):FreqsIndicie(2))',app.BaselineNormalizeCheckBox.Value,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations,[],app.Mainapp.PlotAppearance.SpectrumWindow.GridPlotType,0,Ylims,app.Mainapp.CurrentPlotData);
  
            end
          
        end

        % Menu selected function: ColorDataMenu
        function ColorDataMenuSelected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            %% -------------------- Plot -------------------- 
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3

                app.Mainapp.PlotAppearance.SpectrumWindow.Data.SpectrumColor = selectedColor;

                %% -------------------- Plot -------------------- 

                if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Individual Channel ")
                    app.ChannelDropDown.Enable = "on";
                    app.DataTypeDropDown.Enable = "on";
    
                    set(app.UIAxes, 'YDir', 'normal');
    
                    [app.Mainapp.CurrentPlotData] = Event_Analyse_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,app.SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.SelectedEvents,app.DataToExtractFromDropDown.Value,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
    
                elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power over Depth")
                    app.ChannelDropDown.Enable = "off";
                    app.DataTypeDropDown.Enable = "off";
                    app.FrequencyRangeHzEditField.Enable = "on";
                    
                    [~,app.BandPower,app.Mainapp.CurrentPlotData] = Event_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'All',app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SelectedEvents,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.DataToExtractFromDropDown.Value,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
                
                elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Across Grid")  
    
                    Freqs = str2double(strsplit(app.FrequencyRangeHzEditField.Value,','));
                    [~,FreqsIndicie(1)] = min(abs(app.BandPower.F - Freqs(1)));
                    [~,FreqsIndicie(2)] = min(abs(app.BandPower.F - Freqs(2)));
    
                    EventRelatedSpectrum = app.BandPower.allPowerEst(:,FreqsIndicie(1):FreqsIndicie(2));
        
                    [Channelrange] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.Mainapp.ActiveChannel,'MainPlot');
                    EventRelatedSpectrum = EventRelatedSpectrum(Channelrange,:);
                    
                    Ylims = [min(EventRelatedSpectrum,[],'all') max(EventRelatedSpectrum,[],'all')];
    
                    % EventRelatedData is channel by time
                    [~,app.Mainapp.CurrentPlotData] = Module_Main_Window_Plot_Grid_Trace_View(app,app.Mainapp.Data,EventRelatedSpectrum,app.BandPower.F(FreqsIndicie(1):FreqsIndicie(2))',app.BaselineNormalizeCheckBox.Value,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations,[],app.Mainapp.PlotAppearance.SpectrumWindow.GridPlotType,0,Ylims,app.Mainapp.CurrentPlotData);
      
                end
            end
        end

        % Menu selected function: BackgorundColorMenu
        function BackgorundColorMenuSelected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 

            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.Mainapp.PlotAppearance.SpectrumWindow.Data.SpectrumBackgroundColor = selectedColor;
                app.UIAxes.Color = app.Mainapp.PlotAppearance.SpectrumWindow.Data.SpectrumBackgroundColor;
                app.UIAxes_2.Color = app.Mainapp.PlotAppearance.SpectrumWindow.Data.SpectrumBackgroundColor;
            end
        end

        % Menu selected function: XLabelMenu
        function XLabelMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'xlabel',app.Mainapp,"SpectrumWindowDataXlabel");
            waitfor(h);
            %% -------------------- Plot -------------------- 

            if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Individual Channel ")
                app.ChannelDropDown.Enable = "on";
                app.DataTypeDropDown.Enable = "on";

                set(app.UIAxes, 'YDir', 'normal');

                [app.Mainapp.CurrentPlotData] = Event_Analyse_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,app.SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.SelectedEvents,app.DataToExtractFromDropDown.Value,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);

            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power over Depth")
                app.ChannelDropDown.Enable = "off";
                app.DataTypeDropDown.Enable = "off";
                app.FrequencyRangeHzEditField.Enable = "on";
                
                [~,app.BandPower,app.Mainapp.CurrentPlotData] = Event_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'All',app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SelectedEvents,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.DataToExtractFromDropDown.Value,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
            
            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Across Grid")  

                Freqs = str2double(strsplit(app.FrequencyRangeHzEditField.Value,','));
                [~,FreqsIndicie(1)] = min(abs(app.BandPower.F - Freqs(1)));
                [~,FreqsIndicie(2)] = min(abs(app.BandPower.F - Freqs(2)));

                EventRelatedSpectrum = app.BandPower.allPowerEst(:,FreqsIndicie(1):FreqsIndicie(2));
    
                [Channelrange] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.Mainapp.ActiveChannel,'MainPlot');
                EventRelatedSpectrum = EventRelatedSpectrum(Channelrange,:);
                
                Ylims = [min(EventRelatedSpectrum,[],'all') max(EventRelatedSpectrum,[],'all')];

                % EventRelatedData is channel by time
                [~,app.Mainapp.CurrentPlotData] = Module_Main_Window_Plot_Grid_Trace_View(app,app.Mainapp.Data,EventRelatedSpectrum,app.BandPower.F(FreqsIndicie(1):FreqsIndicie(2))',app.BaselineNormalizeCheckBox.Value,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations,[],app.Mainapp.PlotAppearance.SpectrumWindow.GridPlotType,0,Ylims,app.Mainapp.CurrentPlotData);
  
            end
        end

        % Menu selected function: YLabelMenu
        function YLabelMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'ylabel',app.Mainapp,"SpectrumWindowDataYlabel");
            waitfor(h);
            %% -------------------- Plot -------------------- 

            if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Individual Channel ")
                app.ChannelDropDown.Enable = "on";
                app.DataTypeDropDown.Enable = "on";

                set(app.UIAxes, 'YDir', 'normal');

                [app.Mainapp.CurrentPlotData] = Event_Analyse_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,app.SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.SelectedEvents,app.DataToExtractFromDropDown.Value,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);

            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power over Depth")
                app.ChannelDropDown.Enable = "off";
                app.DataTypeDropDown.Enable = "off";
                app.FrequencyRangeHzEditField.Enable = "on";
                
                [~,app.BandPower,app.Mainapp.CurrentPlotData] = Event_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'All',app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SelectedEvents,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.DataToExtractFromDropDown.Value,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
            
            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Across Grid")  

                Freqs = str2double(strsplit(app.FrequencyRangeHzEditField.Value,','));
                [~,FreqsIndicie(1)] = min(abs(app.BandPower.F - Freqs(1)));
                [~,FreqsIndicie(2)] = min(abs(app.BandPower.F - Freqs(2)));

                EventRelatedSpectrum = app.BandPower.allPowerEst(:,FreqsIndicie(1):FreqsIndicie(2));
    
                [Channelrange] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.Mainapp.ActiveChannel,'MainPlot');
                EventRelatedSpectrum = EventRelatedSpectrum(Channelrange,:);
                
                Ylims = [min(EventRelatedSpectrum,[],'all') max(EventRelatedSpectrum,[],'all')];

                % EventRelatedData is channel by time
                [~,app.Mainapp.CurrentPlotData] = Module_Main_Window_Plot_Grid_Trace_View(app,app.Mainapp.Data,EventRelatedSpectrum,app.BandPower.F(FreqsIndicie(1):FreqsIndicie(2))',app.BaselineNormalizeCheckBox.Value,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations,[],app.Mainapp.PlotAppearance.SpectrumWindow.GridPlotType,0,Ylims,app.Mainapp.CurrentPlotData);
  
            end
        end

        % Menu selected function: RESETMenu
        function RESETMenuSelected(app, event)
        app.UIAxes.NextPlot = "replace"; 
        plot(app.UIAxes,0,0);
        app.UIAxes.NextPlot = "add"; 
        
        TempPlotType = app.Mainapp.PlotAppearance.SpectrumWindow.PlotType;
        TempGirdType = app.Mainapp.PlotAppearance.SpectrumWindow.GridPlotType;

        [app.Mainapp.PlotAppearance] = Organize_Set_Standard_PlotAppearance("SpectrumPlot",app.Mainapp.PlotAppearance);

        app.Mainapp.PlotAppearance.SpectrumWindow.PlotType = TempPlotType;
        app.Mainapp.PlotAppearance.SpectrumWindow.GridPlotType = TempGirdType;
        
        %% -------------------- Plot -------------------- 

            if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Individual Channel ")
                app.ChannelDropDown.Enable = "on";
                app.DataTypeDropDown.Enable = "on";

                set(app.UIAxes, 'YDir', 'normal');

                [app.Mainapp.CurrentPlotData] = Event_Analyse_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,app.SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.SelectedEvents,app.DataToExtractFromDropDown.Value,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);

            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power over Depth")
                app.ChannelDropDown.Enable = "off";
                app.DataTypeDropDown.Enable = "off";
                app.FrequencyRangeHzEditField.Enable = "on";
                
                [~,app.BandPower,app.Mainapp.CurrentPlotData] = Event_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'All',app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SelectedEvents,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.DataToExtractFromDropDown.Value,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
            
            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Across Grid")  

                Freqs = str2double(strsplit(app.FrequencyRangeHzEditField.Value,','));
                [~,FreqsIndicie(1)] = min(abs(app.BandPower.F - Freqs(1)));
                [~,FreqsIndicie(2)] = min(abs(app.BandPower.F - Freqs(2)));

                EventRelatedSpectrum = app.BandPower.allPowerEst(:,FreqsIndicie(1):FreqsIndicie(2));
    
                [Channelrange] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.Mainapp.ActiveChannel,'MainPlot');
                EventRelatedSpectrum = EventRelatedSpectrum(Channelrange,:);
                
                Ylims = [min(EventRelatedSpectrum,[],'all') max(EventRelatedSpectrum,[],'all')];

                % EventRelatedData is channel by time
                [~,app.Mainapp.CurrentPlotData] = Module_Main_Window_Plot_Grid_Trace_View(app,app.Mainapp.Data,EventRelatedSpectrum,app.BandPower.F(FreqsIndicie(1):FreqsIndicie(2))',app.BaselineNormalizeCheckBox.Value,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations,[],app.Mainapp.PlotAppearance.SpectrumWindow.GridPlotType,0,Ylims,app.Mainapp.CurrentPlotData);
  
            end
        end

        % Menu selected function: SaveCustomeAppearanceMenu
        function SaveCustomeAppearanceMenuSelected(app, event)
            app.Mainapp.PlotAppearance = Utility_Save_Load_Delete_Plot_Appearance(app.Mainapp.PlotAppearance,app.Mainapp.executableFolder,"Save");
        end

        % Menu selected function: DeleteCustomeAppearanceMenu
        function DeleteCustomeAppearanceMenuSelected(app, event)
            app.Mainapp.PlotAppearance = Utility_Save_Load_Delete_Plot_Appearance(app.Mainapp.PlotAppearance,app.Mainapp.executableFolder,"Delete");
        end

        % Value changed function: EventSelectionEditField
        function EventSelectionEditFieldValueChanged(app, event)
            %% -------------------- Populate event channel selection -------------------- 
            [app.EventSelectionEditField.Value] = Event_Module_Check_EventInput(app.EventSelectionEditField.Value,app.Mainapp.Data,app.EventTriggerChannel.Value,app.DataSourceDropDown.Value,0);
            
            app.SelectedEvents = eval(app.EventSelectionEditField.Value);
            cla(app.UIAxes_2);
            
            %% -------------------- Plot -------------------- 

            if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Individual Channel ")   
           
                [app.Mainapp.CurrentPlotData] = Event_Analyse_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,app.SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.SelectedEvents,app.DataToExtractFromDropDown.Value,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
                [~,app.BandPower,app.Mainapp.CurrentPlotData] = Event_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'Just Frequency Bands',app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SelectedEvents,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.DataToExtractFromDropDown.Value,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power over Depth")
                [~,app.BandPower,app.Mainapp.CurrentPlotData] = Event_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'All',app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SelectedEvents,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.DataToExtractFromDropDown.Value,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
                
                cb = colorbar(app.UIAxes);
                cb.Color = 'k';              % Sets tick mark and label color to black
                cb.Label.String = "Power [dB]";
                cb.Label.Rotation = 270;
                %cb.FontSize =  app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeFontSize;
                cb.Label.Color = 'k';        % Sets the color of the label text
            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Across Grid")
                [~,app.BandPower,app.Mainapp.CurrentPlotData] = Event_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,"Just Frequency Bands",app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SelectedEvents,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.DataToExtractFromDropDown.Value,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
                
                Freqs = str2double(strsplit(app.FrequencyRangeHzEditField.Value,','));
                [~,FreqsIndicie(1)] = min(abs(app.BandPower.F - Freqs(1)));
                [~,FreqsIndicie(2)] = min(abs(app.BandPower.F - Freqs(2)));

                EventRelatedSpectrum = app.BandPower.allPowerEst(:,FreqsIndicie(1):FreqsIndicie(2));
    
                [Channelrange] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.Mainapp.ActiveChannel,'MainPlot');
                EventRelatedSpectrum = EventRelatedSpectrum(Channelrange,:);
                
                Ylims = [min(EventRelatedSpectrum,[],'all') max(EventRelatedSpectrum,[],'all')];

                % EventRelatedData is channel by time
                [~,app.Mainapp.CurrentPlotData] = Module_Main_Window_Plot_Grid_Trace_View(app,app.Mainapp.Data,EventRelatedSpectrum,app.BandPower.F(FreqsIndicie(1):FreqsIndicie(2))',app.BaselineNormalizeCheckBox.Value,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations,[],app.Mainapp.PlotAppearance.SpectrumWindow.GridPlotType,0,Ylims,app.Mainapp.CurrentPlotData);
  
            end 
            
            
        end

        % Close request function: EventStaticSpectrumWindowUIFigure
        function EventStaticSpectrumWindowUIFigureCloseRequest(app, event)
           try
                app.Mainapp.EventLFPSSP = [];
    
                if ~isempty(app.Mainapp.ProbeViewWindowHandle) % delete option from probe view 
                    for i = 1:length(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                        if strcmp(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Event Static Spectrum Window')
                            app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items(i) = [];
                            break;
                        end
                    end
                else % if no probe view available
     
                end
            catch

            end

            delete(app);
            
        end

        % Value changed function: DataToExtractFromDropDown
        function DataToExtractFromDropDownValueChanged(app, event)
            %% -------------------- Extract Event Related Data -------------------- 
            [app.Mainapp.Data,~] = Event_Module_Extract_Event_Related_Data(app.Mainapp.Data,app.EventTriggerChannel.Value,app.TimearoundEvent,app.DataToExtractFromDropDown.Value,app.DataSourceDropDown.Value);
            
            %% -------------------- Plot -------------------- 
            if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Individual Channel ")
                app.ChannelDropDown.Enable = "on";
                app.DataTypeDropDown.Enable = "on";

                [app.Mainapp.CurrentPlotData] = Event_Analyse_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,app.SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.SelectedEvents,app.DataToExtractFromDropDown.Value,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);

                [~,app.BandPower,app.Mainapp.CurrentPlotData] = Event_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'Just Frequency Bands',app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SelectedEvents,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.DataToExtractFromDropDown.Value,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
            
            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power over Depth")
                app.ChannelDropDown.Enable = "off";
                app.DataTypeDropDown.Enable = "off";

                [~,app.BandPower,app.Mainapp.CurrentPlotData] = Event_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'All',app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SelectedEvents,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.DataToExtractFromDropDown.Value,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
                
                cb = colorbar(app.UIAxes);
                cb.Color = 'k';              % Sets tick mark and label color to black
                cb.Label.String = "Power [dB]";
                cb.Label.Rotation = 270;
                %cb.FontSize =  app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeFontSize;
                cb.Label.Color = 'k';        % Sets the color of the label text
            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Across Grid")
                [~,app.BandPower,app.Mainapp.CurrentPlotData] = Event_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,"Just Frequency Bands",app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SelectedEvents,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.DataToExtractFromDropDown.Value,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
                
                Freqs = str2double(strsplit(app.FrequencyRangeHzEditField.Value,','));
                [~,FreqsIndicie(1)] = min(abs(app.BandPower.F - Freqs(1)));
                [~,FreqsIndicie(2)] = min(abs(app.BandPower.F - Freqs(2)));

                EventRelatedSpectrum = app.BandPower.allPowerEst(:,FreqsIndicie(1):FreqsIndicie(2));
    
                [Channelrange] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.Mainapp.ActiveChannel,'MainPlot');
                EventRelatedSpectrum = EventRelatedSpectrum(Channelrange,:);
                
                Ylims = [min(EventRelatedSpectrum,[],'all') max(EventRelatedSpectrum,[],'all')];

                % EventRelatedData is channel by time
                [~,app.Mainapp.CurrentPlotData] = Module_Main_Window_Plot_Grid_Trace_View(app,app.Mainapp.Data,EventRelatedSpectrum,app.BandPower.F(FreqsIndicie(1):FreqsIndicie(2))',app.BaselineNormalizeCheckBox.Value,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations,[],app.Mainapp.PlotAppearance.SpectrumWindow.GridPlotType,0,Ylims,app.Mainapp.CurrentPlotData);
  
            end
        end

        % Value changed function: EventTriggerChannel
        function EventTriggerChannelValueChanged(app, event)
            %% -------------------- Check if prepro event related data found and can be offered -------------------- 
            [texttoshow,Value] = Event_Module_Check_Event_Preprocessing(app.Mainapp.Data,app.EventTriggerChannel.Value,app.DataSourceDropDown.Value);
            app.DataSourceDropDown.Items = {};
            app.DataSourceDropDown.Items = texttoshow;
            app.DataSourceDropDown.Value = Value;

            %% -------------------- Extract Event Related Data -------------------- 
            [app.Mainapp.Data,~] = Event_Module_Extract_Event_Related_Data(app.Mainapp.Data,app.EventTriggerChannel.Value,app.TimearoundEvent,app.DataToExtractFromDropDown.Value,app.DataSourceDropDown.Value);
            
            %% -------------------- Set Basline Normlaization and trigger number -------------------- 
            if strcmp(app.DataSourceDropDown.Value,'Raw Event Related Data')
                app.EventNumberSelectionEditField_2.Value = strcat(num2str(size(app.Mainapp.Data.EventRelatedData,2)),' Trigger');
            else
                app.EventNumberSelectionEditField_2.Value = strcat(num2str(size(app.Mainapp.Data.PreprocessedEventRelatedData,2)),' Trigger');
            end

            %% -------------------- Populate event channel selection -------------------- 
            [app.EventSelectionEditField.Value] = Event_Module_Check_EventInput(app.EventSelectionEditField.Value,app.Mainapp.Data,app.EventTriggerChannel.Value,app.DataSourceDropDown.Value,1);
            
            app.SelectedEvents = eval(app.EventSelectionEditField.Value);

            %% -------------------- Plot -------------------- 
            if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Individual Channel ")
                app.ChannelDropDown.Enable = "on";
                app.DataTypeDropDown.Enable = "on";

                [app.Mainapp.CurrentPlotData] = Event_Analyse_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,app.SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.SelectedEvents,app.DataToExtractFromDropDown.Value,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);

                [~,app.BandPower,app.Mainapp.CurrentPlotData] = Event_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'Just Frequency Bands',app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SelectedEvents,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.DataToExtractFromDropDown.Value,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
            
            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power over Depth")
                app.ChannelDropDown.Enable = "off";
                app.DataTypeDropDown.Enable = "off";

                [~,app.BandPower,app.Mainapp.CurrentPlotData] = Event_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'All',app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SelectedEvents,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.DataToExtractFromDropDown.Value,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
                
                cb = colorbar(app.UIAxes);
                cb.Color = 'k';              % Sets tick mark and label color to black
                cb.Label.String = "Power [dB]";
                cb.Label.Rotation = 270;
                %cb.FontSize =  app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeFontSize;
                cb.Label.Color = 'k';        % Sets the color of the label text
            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Across Grid")

                [~,app.BandPower,app.Mainapp.CurrentPlotData] = Event_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,"Just Frequency Bands",app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SelectedEvents,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.DataToExtractFromDropDown.Value,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
                

                Freqs = str2double(strsplit(app.FrequencyRangeHzEditField.Value,','));
                [~,FreqsIndicie(1)] = min(abs(app.BandPower.F - Freqs(1)));
                [~,FreqsIndicie(2)] = min(abs(app.BandPower.F - Freqs(2)));

                EventRelatedSpectrum = app.BandPower.allPowerEst(:,FreqsIndicie(1):FreqsIndicie(2));
    
                [Channelrange] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.Mainapp.ActiveChannel,'MainPlot');
                EventRelatedSpectrum = EventRelatedSpectrum(Channelrange,:);
                
                Ylims = [min(EventRelatedSpectrum,[],'all') max(EventRelatedSpectrum,[],'all')];

                % EventRelatedData is channel by time
                [~,app.Mainapp.CurrentPlotData] = Module_Main_Window_Plot_Grid_Trace_View(app,app.Mainapp.Data,EventRelatedSpectrum,app.BandPower.F(FreqsIndicie(1):FreqsIndicie(2))',app.BaselineNormalizeCheckBox.Value,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations,[],app.Mainapp.PlotAppearance.SpectrumWindow.GridPlotType,0,Ylims,app.Mainapp.CurrentPlotData);
  
            end
        end

        % Drop down opening function: DataTypeDropDown
        function DataTypeDropDownOpening(app, event)
            
        end

        % Menu selected function: SVGMenu
        function SVGMenuSelected(app, event)
            
        end

        % Menu selected function: xlsxMenu_3
        function xlsxMenuSelected(app, event)
            TimeRangePlot = str2double(app.Mainapp.TimeRangeViewBox.Value(1:end-1));
            if strcmp(app.Mainapp.DropDown.Value,"Preprocessed")
                if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                else
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                end
            else
                StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
            end
            
            if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Individual Channel ")
                Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".xlsx",app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Event Related Static Spectrum Individual Channel",0);
            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power over Depth")
                Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".xlsx",app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Event Related Static Spectrum over Depth",0);
            end
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            Utility_Open_UIAxes_as_Figure(app.Mainapp.Data,app.UIAxes,strcat("EventStaticSpectrum ",app.Mainapp.PlotAppearance.SpectrumWindow.PlotType));
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            Utility_Open_UIAxes_as_Figure(app.Mainapp.Data,app.UIAxes_2,strcat("EventStaticSpectrum2"));
        end

        % Value changed function: BaselineNormalizeCheckBox
        function BaselineNormalizeCheckBoxValueChanged(app, event)

            %% -------------------- Plot -------------------- 
            if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Individual Channel ")
                app.ChannelDropDown.Enable = "on";
                app.DataTypeDropDown.Enable = "on";

                [app.Mainapp.CurrentPlotData] = Event_Analyse_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,app.SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.SelectedEvents,app.DataToExtractFromDropDown.Value,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);

                [~,app.BandPower,app.Mainapp.CurrentPlotData] = Event_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'Just Frequency Bands',app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SelectedEvents,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.DataToExtractFromDropDown.Value,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
            
            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power over Depth")
                app.ChannelDropDown.Enable = "off";
                app.DataTypeDropDown.Enable = "off";

                [~,app.BandPower,app.Mainapp.CurrentPlotData] = Event_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'All',app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SelectedEvents,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.DataToExtractFromDropDown.Value,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
                
                cb = colorbar(app.UIAxes);
                cb.Color = 'k';              % Sets tick mark and label color to black
                cb.Label.String = "Power [dB]";
                cb.Label.Rotation = 270;
                %cb.FontSize =  app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeFontSize;
                cb.Label.Color = 'k';        % Sets the color of the label text
            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Across Grid")

                [~,app.BandPower,app.Mainapp.CurrentPlotData] = Event_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'Just Frequency Bands',app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SelectedEvents,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.DataToExtractFromDropDown.Value,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
            
                Freqs = str2double(strsplit(app.FrequencyRangeHzEditField.Value,','));
                [~,FreqsIndicie(1)] = min(abs(app.BandPower.F - Freqs(1)));
                [~,FreqsIndicie(2)] = min(abs(app.BandPower.F - Freqs(2)));

                EventRelatedSpectrum = app.BandPower.allPowerEst(:,FreqsIndicie(1):FreqsIndicie(2));
    
                [Channelrange] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.Mainapp.ActiveChannel,'MainPlot');
                EventRelatedSpectrum = EventRelatedSpectrum(Channelrange,:);
                
                Ylims = [min(EventRelatedSpectrum,[],'all') max(EventRelatedSpectrum,[],'all')];

                % EventRelatedData is channel by time
                [~,app.Mainapp.CurrentPlotData] = Module_Main_Window_Plot_Grid_Trace_View(app,app.Mainapp.Data,EventRelatedSpectrum,app.BandPower.F(FreqsIndicie(1):FreqsIndicie(2))',app.BaselineNormalizeCheckBox.Value,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations,[],app.Mainapp.PlotAppearance.SpectrumWindow.GridPlotType,0,Ylims,app.Mainapp.CurrentPlotData);
  
            end
            
        end

        % Value changed function: EventNumberSelectionEditField
        function EventNumberSelectionEditFieldValueChanged(app, event)

            %% -------------------- Plot -------------------- 
            if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Individual Channel ")
                app.ChannelDropDown.Enable = "on";
                app.DataTypeDropDown.Enable = "on";

                [app.Mainapp.CurrentPlotData] = Event_Analyse_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,app.SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.SelectedEvents,app.DataToExtractFromDropDown.Value,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);

                [~,app.BandPower,app.Mainapp.CurrentPlotData] = Event_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'Just Frequency Bands',app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SelectedEvents,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.DataToExtractFromDropDown.Value,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
            
            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power over Depth")
                app.ChannelDropDown.Enable = "off";
                app.DataTypeDropDown.Enable = "off";

                [~,app.BandPower,app.Mainapp.CurrentPlotData] = Event_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'All',app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SelectedEvents,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.DataToExtractFromDropDown.Value,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
                
                cb = colorbar(app.UIAxes);
                cb.Color = 'k';              % Sets tick mark and label color to black
                cb.Label.String = "Power [dB]";
                cb.Label.Rotation = 270;
                %cb.FontSize =  app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeFontSize;
                cb.Label.Color = 'k';        % Sets the color of the label text
            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Across Grid")
                Freqs = str2double(strsplit(app.FrequencyRangeHzEditField.Value,','));
                [~,FreqsIndicie(1)] = min(abs(app.BandPower.F - Freqs(1)));
                [~,FreqsIndicie(2)] = min(abs(app.BandPower.F - Freqs(2)));

                EventRelatedSpectrum = app.BandPower.allPowerEst(:,FreqsIndicie(1):FreqsIndicie(2));
    
                [Channelrange] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.Mainapp.ActiveChannel,'MainPlot');
                EventRelatedSpectrum = EventRelatedSpectrum(Channelrange,:);
                
                Ylims = [min(EventRelatedSpectrum,[],'all') max(EventRelatedSpectrum,[],'all')];

                % EventRelatedData is channel by time
                [~,app.Mainapp.CurrentPlotData] = Module_Main_Window_Plot_Grid_Trace_View(app,app.Mainapp.Data,EventRelatedSpectrum,app.BandPower.F(FreqsIndicie(1):FreqsIndicie(2))',app.BaselineNormalizeCheckBox.Value,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations,[],app.Mainapp.PlotAppearance.SpectrumWindow.GridPlotType,0,Ylims,app.Mainapp.CurrentPlotData);
  
            end
            
        end

        % Menu selected function: IndividualChannelViewMenu
        function IndividualChannelViewMenuSelected(app, event)
            app.Mainapp.PlotAppearance.SpectrumWindow.PlotType = "Band Power Individual Channel ";
            
            if ~isempty(app.ChannelAxes)
                for k = 1:numel(app.ChannelAxes)
                    if isvalid(app.ChannelAxes{k})  
                        delete(app.ChannelAxes{k});
                    end
                end
                app.ChannelAxes = [];
            end
            if ~isempty(app.ChannelGrid)
                delete(app.ChannelGrid)
            end

            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";

            %cla(app.UIAxes)

            PowerDepth_handles = findobj(app.UIAxes, 'Tag', 'PowerDepth');
            delete(PowerDepth_handles(1:end));

            PWelch_handles = findobj(app.UIAxes, 'Tag', 'Pwelch');
            delete(PWelch_handles(1:end));

            app.UIAxes.Color = app.Mainapp.PlotAppearance.SpectrumWindow.Data.SpectrumBackgroundColor;
            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeFontSize;
            
            %% -------------------- Plot -------------------- 
            if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Individual Channel ")
                app.Grid_Traces_View_Panel.Visible = "off";
                app.Grid_Traces_View_Panel.Enable = "off";
                app.Button_2.Visible = "on";
                app.Button_2.Enable = "on";

                if ~isempty(app.ChannelAxes)
                    for k = 1:numel(app.ChannelAxes)
                        if isvalid(app.ChannelAxes{k})  
                            delete(app.ChannelAxes{k});
                        end
                    end
                    app.ChannelAxes = [];
                end
                if ~isempty(app.ChannelGrid)
                    delete(app.ChannelGrid)
                end

                app.ChannelDropDown.Enable = "on";
                app.DataTypeDropDown.Enable = "on";
                app.FrequencyRangeHzEditField.Enable = "on";
                
                if ~strcmp(app.UIAxes.YDir, 'normal')
                    set(app.UIAxes, 'YDir', 'normal');
                end
                cb = colorbar(app.UIAxes);   % Create a colorbar (if it exists)
                if ~isempty(cb)
                    delete(cb);                  % Delete the colorbar
                end

                app.UIAxes.Color  = app.Mainapp.PlotAppearance.SpectrumWindow.Data.SpectrumBackgroundColor;
                app.UIAxes.XColor = 'k';  
                app.UIAxes.YColor = 'k';  
            
                app.UIAxes.XTickLabelMode = 'auto';  
                app.UIAxes.YTickLabelMode = 'auto';
                app.UIAxes.TickLabelInterpreter = 'none';  % Avoid LaTeX/TeX interpretation if not needed
                app.UIAxes.FontSize = app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeFontSize;
                %app.UIAxes.FontColor = 'k';  % Tick label color
                
                % Set title, xlabel, ylabel colors
                app.UIAxes.Title.Color = 'k';
                app.UIAxes.XLabel.Color = 'k';
                app.UIAxes.YLabel.Color = 'k';
                
                [~,app.BandPower,app.Mainapp.CurrentPlotData] = Event_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'Just Frequency Bands',app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SelectedEvents,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.DataToExtractFromDropDown.Value,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);

                [app.Mainapp.CurrentPlotData] = Event_Analyse_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,app.SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.SelectedEvents,app.DataToExtractFromDropDown.Value,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
     

            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power over Depth")
                app.Grid_Traces_View_Panel.Visible = "off";
                app.Grid_Traces_View_Panel.Enable = "off";
                app.Button_2.Visible = "on";
                app.Button_2.Enable = "on";

                if ~isempty(app.ChannelAxes)
                    for k = 1:numel(app.ChannelAxes)
                        if isvalid(app.ChannelAxes{k})  
                            delete(app.ChannelAxes{k});
                        end
                    end
                    app.ChannelAxes = [];
                end
                if ~isempty(app.ChannelGrid)
                    delete(app.ChannelGrid)
                end

                app.ChannelDropDown.Enable = "off";
                app.DataTypeDropDown.Enable = "off";
                app.FrequencyRangeHzEditField.Enable = "on";

                %app.UIAxes.YScale = 'linear';
                
                [~,app.BandPower,app.Mainapp.CurrentPlotData] = Event_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'All',app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SelectedEvents,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.DataToExtractFromDropDown.Value,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);

                cb = colorbar(app.UIAxes);
                cb.Color = 'k';              % Sets tick mark and label color to black
                cb.Label.String = "Power [dB]";
                cb.Label.Rotation = 270;
                %cb.FontSize =  app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeFontSize;
                cb.Label.Color = 'k';        % Sets the color of the label text
            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Across Grid")

                % if str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)<=2
                %     msgbox("At least 3 channel rows required! Returning");
                %     return;
                % end
                
                app.Grid_Traces_View_Panel.Visible = "on";
                app.Grid_Traces_View_Panel.Enable = "on";
                app.Button_2.Visible = "off";
                app.Button_2.Enable = "off";

                app.ChannelDropDown.Enable = "off";
                app.DataTypeDropDown.Enable = "off";
                app.FrequencyRangeHzEditField.Enable = "on";
    
                if isempty(app.ChannelAxes)
                    app = Module_MainWindow_Initialize_Grid_Trace_Panel(app,app.Mainapp,"StaticEventSpectrum");
                end         

                Freqs = str2double(strsplit(app.FrequencyRangeHzEditField.Value,','));
                [~,FreqsIndicie(1)] = min(abs(app.BandPower.F - Freqs(1)));
                [~,FreqsIndicie(2)] = min(abs(app.BandPower.F - Freqs(2)));

                EventRelatedSpectrum = app.BandPower.allPowerEst(:,FreqsIndicie(1):FreqsIndicie(2));
    
                [Channelrange] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.Mainapp.ActiveChannel,'MainPlot');
                EventRelatedSpectrum = EventRelatedSpectrum(Channelrange,:);
                
                Ylims = [min(EventRelatedSpectrum,[],'all') max(EventRelatedSpectrum,[],'all')];

                % EventRelatedData is channel by time
                [~,app.Mainapp.CurrentPlotData] = Module_Main_Window_Plot_Grid_Trace_View(app,app.Mainapp.Data,EventRelatedSpectrum,app.BandPower.F(FreqsIndicie(1):FreqsIndicie(2))',app.BaselineNormalizeCheckBox.Value,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations,[],app.Mainapp.PlotAppearance.SpectrumWindow.GridPlotType,0,Ylims,app.Mainapp.CurrentPlotData);
  
            end

        end

        % Menu selected function: LinesMenu
        function LinesMenuSelected(app, event)
            app.Mainapp.PlotAppearance.SpectrumWindow.PlotType = "Band Power Across Grid";
            app.Mainapp.PlotAppearance.SpectrumWindow.GridPlotType = "EventWindowLinesSpectrum";

            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";
            
            if ~isempty(app.ChannelAxes)
                for k = 1:numel(app.ChannelAxes)
                    if isvalid(app.ChannelAxes{k})  
                        delete(app.ChannelAxes{k});
                    end
                end
                app.ChannelAxes = [];
            end
            if ~isempty(app.ChannelGrid)
                delete(app.ChannelGrid)
            end

            cla(app.UIAxes)

            PowerDepth_handles = findobj(app.UIAxes, 'Tag', 'PowerDepth');
            delete(PowerDepth_handles(1:end));

            PWelch_handles = findobj(app.UIAxes, 'Tag', 'Pwelch');
            delete(PWelch_handles(1:end));

            app.UIAxes.Color = app.Mainapp.PlotAppearance.SpectrumWindow.Data.SpectrumBackgroundColor;
            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeFontSize;
            
            %% -------------------- Plot -------------------- 
            if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Individual Channel ")
                app.Grid_Traces_View_Panel.Visible = "off";
                app.Grid_Traces_View_Panel.Enable = "off";
                app.Button_2.Visible = "on";
                app.Button_2.Enable = "on";

                if ~isempty(app.ChannelAxes)
                    for k = 1:numel(app.ChannelAxes)
                        if isvalid(app.ChannelAxes{k})  
                            delete(app.ChannelAxes{k});
                        end
                    end
                    app.ChannelAxes = [];
                end
                if ~isempty(app.ChannelGrid)
                    delete(app.ChannelGrid)
                end

                app.ChannelDropDown.Enable = "on";
                app.DataTypeDropDown.Enable = "on";
                app.FrequencyRangeHzEditField.Enable = "on";

                set(app.UIAxes, 'YDir', 'normal');
                cb = colorbar(app.UIAxes);   % Create a colorbar (if it exists)
                if ~isempty(cb)
                    delete(cb);                  % Delete the colorbar
                end

                [app.Mainapp.CurrentPlotData] = Event_Analyse_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,app.SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.SelectedEvents,app.DataToExtractFromDropDown.Value,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);

            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power over Depth")
                app.Grid_Traces_View_Panel.Visible = "off";
                app.Grid_Traces_View_Panel.Enable = "off";
                app.Button_2.Visible = "on";
                app.Button_2.Enable = "on";

                if ~isempty(app.ChannelAxes)
                    for k = 1:numel(app.ChannelAxes)
                        if isvalid(app.ChannelAxes{k})  
                            delete(app.ChannelAxes{k});
                        end
                    end
                    app.ChannelAxes = [];
                end
                if ~isempty(app.ChannelGrid)
                    delete(app.ChannelGrid)
                end

                app.ChannelDropDown.Enable = "off";
                app.DataTypeDropDown.Enable = "off";
                app.FrequencyRangeHzEditField.Enable = "on";

                %app.UIAxes.YScale = 'linear';
                
                [~,app.BandPower,app.Mainapp.CurrentPlotData] = Event_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'All',app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SelectedEvents,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.DataToExtractFromDropDown.Value,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);

                cb = colorbar(app.UIAxes);
                cb.Color = 'k';              % Sets tick mark and label color to black
                cb.Label.String = "Power [dB]";
                cb.Label.Rotation = 270;
                %cb.FontSize =  app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeFontSize;
                cb.Label.Color = 'k';        % Sets the color of the label text
            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Across Grid")

                % if str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)<=2
                %     msgbox("At least 3 channel rows required! Returning");
                %     return;
                % end
                
                app.Grid_Traces_View_Panel.Visible = "on";
                app.Grid_Traces_View_Panel.Enable = "on";
                app.Button_2.Visible = "off";
                app.Button_2.Enable = "off";

                app.ChannelDropDown.Enable = "off";
                app.DataTypeDropDown.Enable = "off";
                app.FrequencyRangeHzEditField.Enable = "on";
    
                if isempty(app.ChannelAxes)
                    app = Module_MainWindow_Initialize_Grid_Trace_Panel(app,app.Mainapp,"StaticEventSpectrum");
                end         

                Freqs = str2double(strsplit(app.FrequencyRangeHzEditField.Value,','));
                [~,FreqsIndicie(1)] = min(abs(app.BandPower.F - Freqs(1)));
                [~,FreqsIndicie(2)] = min(abs(app.BandPower.F - Freqs(2)));

                EventRelatedSpectrum = app.BandPower.allPowerEst(:,FreqsIndicie(1):FreqsIndicie(2));
    
                [Channelrange] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.Mainapp.ActiveChannel,'MainPlot');
                EventRelatedSpectrum = EventRelatedSpectrum(Channelrange,:);
                
                Ylims = [min(EventRelatedSpectrum,[],'all') max(EventRelatedSpectrum,[],'all')];

                % EventRelatedData is channel by time
                [~,app.Mainapp.CurrentPlotData] = Module_Main_Window_Plot_Grid_Trace_View(app,app.Mainapp.Data,EventRelatedSpectrum,app.BandPower.F(FreqsIndicie(1):FreqsIndicie(2))',app.BaselineNormalizeCheckBox.Value,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations,[],app.Mainapp.PlotAppearance.SpectrumWindow.GridPlotType,0,Ylims,app.Mainapp.CurrentPlotData);
  
            end
        end

        % Menu selected function: ColorplotMenu
        function ColorplotMenuSelected(app, event)
            app.Mainapp.PlotAppearance.SpectrumWindow.PlotType = "Band Power Across Grid";
            app.Mainapp.PlotAppearance.SpectrumWindow.GridPlotType = "EventWindowColorSpectrum";
            
            if ~isempty(app.ChannelAxes)
                for k = 1:numel(app.ChannelAxes)
                    if isvalid(app.ChannelAxes{k})  
                        delete(app.ChannelAxes{k});
                    end
                end
                app.ChannelAxes = [];
            end
            if ~isempty(app.ChannelGrid)
                delete(app.ChannelGrid)
            end

            cla(app.UIAxes)

            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";

            PowerDepth_handles = findobj(app.UIAxes, 'Tag', 'PowerDepth');
            delete(PowerDepth_handles(1:end));

            PWelch_handles = findobj(app.UIAxes, 'Tag', 'Pwelch');
            delete(PWelch_handles(1:end));

            app.UIAxes.Color = app.Mainapp.PlotAppearance.SpectrumWindow.Data.SpectrumBackgroundColor;
            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeFontSize;
            
            %% -------------------- Plot -------------------- 
            if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Individual Channel ")
                app.Grid_Traces_View_Panel.Visible = "off";
                app.Grid_Traces_View_Panel.Enable = "off";
                app.Button_2.Visible = "on";
                app.Button_2.Enable = "on";

                if ~isempty(app.ChannelAxes)
                    for k = 1:numel(app.ChannelAxes)
                        if isvalid(app.ChannelAxes{k})  
                            delete(app.ChannelAxes{k});
                        end
                    end
                    app.ChannelAxes = [];
                end
                if ~isempty(app.ChannelGrid)
                    delete(app.ChannelGrid)
                end

                app.ChannelDropDown.Enable = "on";
                app.DataTypeDropDown.Enable = "on";
                app.FrequencyRangeHzEditField.Enable = "on";

                set(app.UIAxes, 'YDir', 'normal');
                cb = colorbar(app.UIAxes);   % Create a colorbar (if it exists)
                if ~isempty(cb)
                    delete(cb);                  % Delete the colorbar
                end

                [app.Mainapp.CurrentPlotData] = Event_Analyse_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,app.SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.SelectedEvents,app.DataToExtractFromDropDown.Value,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);

            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power over Depth")
                app.Grid_Traces_View_Panel.Visible = "off";
                app.Grid_Traces_View_Panel.Enable = "off";
                app.Button_2.Visible = "on";
                app.Button_2.Enable = "on";

                if ~isempty(app.ChannelAxes)
                    for k = 1:numel(app.ChannelAxes)
                        if isvalid(app.ChannelAxes{k})  
                            delete(app.ChannelAxes{k});
                        end
                    end
                    app.ChannelAxes = [];
                end
                if ~isempty(app.ChannelGrid)
                    delete(app.ChannelGrid)
                end

                app.ChannelDropDown.Enable = "off";
                app.DataTypeDropDown.Enable = "off";
                app.FrequencyRangeHzEditField.Enable = "on";

                %app.UIAxes.YScale = 'linear';
                
                [~,app.BandPower,app.Mainapp.CurrentPlotData] = Event_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'All',app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SelectedEvents,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.DataToExtractFromDropDown.Value,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);

                cb = colorbar(app.UIAxes);
                cb.Color = 'k';              % Sets tick mark and label color to black
                cb.Label.String = "Power [dB]";
                cb.Label.Rotation = 270;
                %cb.FontSize =  app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeFontSize;
                cb.Label.Color = 'k';        % Sets the color of the label text
            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Across Grid")

                % if str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)<=2
                %     msgbox("At least 3 channel rows required! Returning");
                %     return;
                % end
                
                app.Grid_Traces_View_Panel.Visible = "on";
                app.Grid_Traces_View_Panel.Enable = "on";
                app.Button_2.Visible = "off";
                app.Button_2.Enable = "off";

                app.ChannelDropDown.Enable = "off";
                app.DataTypeDropDown.Enable = "off";
                app.FrequencyRangeHzEditField.Enable = "on";
    
                if isempty(app.ChannelAxes)
                    app = Module_MainWindow_Initialize_Grid_Trace_Panel(app,app.Mainapp,"StaticEventSpectrum");
                end         

                Freqs = str2double(strsplit(app.FrequencyRangeHzEditField.Value,','));
                [~,FreqsIndicie(1)] = min(abs(app.BandPower.F - Freqs(1)));
                [~,FreqsIndicie(2)] = min(abs(app.BandPower.F - Freqs(2)));

                EventRelatedSpectrum = app.BandPower.allPowerEst(:,FreqsIndicie(1):FreqsIndicie(2));
    
                [Channelrange] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.Mainapp.ActiveChannel,'MainPlot');
                EventRelatedSpectrum = EventRelatedSpectrum(Channelrange,:);
                
                Ylims = [min(EventRelatedSpectrum,[],'all') max(EventRelatedSpectrum,[],'all')];

                % EventRelatedData is channel by time
                [~,app.Mainapp.CurrentPlotData] = Module_Main_Window_Plot_Grid_Trace_View(app,app.Mainapp.Data,EventRelatedSpectrum,app.BandPower.F(FreqsIndicie(1):FreqsIndicie(2))',app.BaselineNormalizeCheckBox.Value,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations,[],app.Mainapp.PlotAppearance.SpectrumWindow.GridPlotType,0,Ylims,app.Mainapp.CurrentPlotData);
  
            end
        end

        % Menu selected function: LineWidthDataMenu_2
        function LineWidthDataMenu_2Selected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'linewidth', app.Mainapp,"EventSpectrumGridDataLineWidth");
            waitfor(h);

            %% -------------------- Plot -------------------- 
            if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Individual Channel ")
                app.ChannelDropDown.Enable = "on";
                app.DataTypeDropDown.Enable = "on";

                [app.Mainapp.CurrentPlotData] = Event_Analyse_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,app.SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.SelectedEvents,app.DataToExtractFromDropDown.Value,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);

                [~,app.BandPower,app.Mainapp.CurrentPlotData] = Event_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'Just Frequency Bands',app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SelectedEvents,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.DataToExtractFromDropDown.Value,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
            
            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power over Depth")
                app.ChannelDropDown.Enable = "off";
                app.DataTypeDropDown.Enable = "off";

                [~,app.BandPower,app.Mainapp.CurrentPlotData] = Event_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'All',app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SelectedEvents,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.DataToExtractFromDropDown.Value,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
                
                cb = colorbar(app.UIAxes);
                cb.Color = 'k';              % Sets tick mark and label color to black
                cb.Label.String = "Power [dB]";
                cb.Label.Rotation = 270;
                %cb.FontSize =  app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeFontSize;
                cb.Label.Color = 'k';        % Sets the color of the label text
            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Across Grid")
                Freqs = str2double(strsplit(app.FrequencyRangeHzEditField.Value,','));
                [~,FreqsIndicie(1)] = min(abs(app.BandPower.F - Freqs(1)));
                [~,FreqsIndicie(2)] = min(abs(app.BandPower.F - Freqs(2)));

                EventRelatedSpectrum = app.BandPower.allPowerEst(:,FreqsIndicie(1):FreqsIndicie(2));
    
                [Channelrange] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.Mainapp.ActiveChannel,'MainPlot');
                EventRelatedSpectrum = EventRelatedSpectrum(Channelrange,:);
                
                Ylims = [min(EventRelatedSpectrum,[],'all') max(EventRelatedSpectrum,[],'all')];

                % EventRelatedData is channel by time
                [~,app.Mainapp.CurrentPlotData] = Module_Main_Window_Plot_Grid_Trace_View(app,app.Mainapp.Data,EventRelatedSpectrum,app.BandPower.F(FreqsIndicie(1):FreqsIndicie(2))',app.BaselineNormalizeCheckBox.Value,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations,[],app.Mainapp.PlotAppearance.SpectrumWindow.GridPlotType,0,Ylims,app.Mainapp.CurrentPlotData);
  
            end
        end

        % Menu selected function: ColorDataMenu_2
        function ColorDataMenu_2Selected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                 app.Mainapp.PlotAppearance.SpectrumWindow.Data.GridTraceDataColor = selectedColor;
            end

            %% -------------------- Plot -------------------- 
            if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Individual Channel ")
                app.ChannelDropDown.Enable = "on";
                app.DataTypeDropDown.Enable = "on";

                [app.Mainapp.CurrentPlotData] = Event_Analyse_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,app.SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.SelectedEvents,app.DataToExtractFromDropDown.Value,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);

                [~,app.BandPower,app.Mainapp.CurrentPlotData] = Event_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'Just Frequency Bands',app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SelectedEvents,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.DataToExtractFromDropDown.Value,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
            
            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power over Depth")
                app.ChannelDropDown.Enable = "off";
                app.DataTypeDropDown.Enable = "off";

                [~,app.BandPower,app.Mainapp.CurrentPlotData] = Event_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'All',app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SelectedEvents,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.DataToExtractFromDropDown.Value,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
                
                cb = colorbar(app.UIAxes);
                cb.Color = 'k';              % Sets tick mark and label color to black
                cb.Label.String = "Power [dB]";
                cb.Label.Rotation = 270;
                %cb.FontSize =  app.Mainapp.PlotAppearance.SpectrumWindow.Data.TimeFontSize;
                cb.Label.Color = 'k';        % Sets the color of the label text
            elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Across Grid")
                Freqs = str2double(strsplit(app.FrequencyRangeHzEditField.Value,','));
                [~,FreqsIndicie(1)] = min(abs(app.BandPower.F - Freqs(1)));
                [~,FreqsIndicie(2)] = min(abs(app.BandPower.F - Freqs(2)));

                EventRelatedSpectrum = app.BandPower.allPowerEst(:,FreqsIndicie(1):FreqsIndicie(2));
    
                [Channelrange] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.Mainapp.ActiveChannel,'MainPlot');
                EventRelatedSpectrum = EventRelatedSpectrum(Channelrange,:);
                
                Ylims = [min(EventRelatedSpectrum,[],'all') max(EventRelatedSpectrum,[],'all')];

                % EventRelatedData is channel by time
                [~,app.Mainapp.CurrentPlotData] = Module_Main_Window_Plot_Grid_Trace_View(app,app.Mainapp.Data,EventRelatedSpectrum,app.BandPower.F(FreqsIndicie(1):FreqsIndicie(2))',app.BaselineNormalizeCheckBox.Value,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations,[],app.Mainapp.PlotAppearance.SpectrumWindow.GridPlotType,0,Ylims,app.Mainapp.CurrentPlotData);
  
            end
        end

        % Menu selected function: RESETMenu_2
        function RESETMenu_2Selected(app, event)
        app.UIAxes.NextPlot = "replace"; 
        plot(app.UIAxes,0,0);
        app.UIAxes.NextPlot = "add"; 

        TempPlotType = app.Mainapp.PlotAppearance.SpectrumWindow.PlotType;
        TempGirdType = app.Mainapp.PlotAppearance.SpectrumWindow.GridPlotType;
        
        [app.Mainapp.PlotAppearance] = Organize_Set_Standard_PlotAppearance("SpectrumPlot",app.Mainapp.PlotAppearance);

        app.Mainapp.PlotAppearance.SpectrumWindow.PlotType = TempPlotType;
        app.Mainapp.PlotAppearance.SpectrumWindow.GridPlotType = TempGirdType;
        %% -------------------- Plot -------------------- 

        if strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Individual Channel ")
            app.ChannelDropDown.Enable = "on";
            app.DataTypeDropDown.Enable = "on";

            set(app.UIAxes, 'YDir', 'normal');

            [app.Mainapp.CurrentPlotData] = Event_Analyse_Static_Power_Spectrum(app.Mainapp.Data,app.UIAxes,app.DataTypeDropDown.Value,app.DataSourceDropDown.Value,app.SelectedChannel,app.ChannelDropDown.Value,app.FrequencyRangeHzEditField.Value,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.SelectedEvents,app.DataToExtractFromDropDown.Value,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);

        elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power over Depth")
            app.ChannelDropDown.Enable = "off";
            app.DataTypeDropDown.Enable = "off";
            app.FrequencyRangeHzEditField.Enable = "on";
            
            [~,app.BandPower,app.Mainapp.CurrentPlotData] = Event_Power_Spectrum_Over_Depth(app.Mainapp.Data,app.DataSourceDropDown.Value,app.BandPower,app.FrequencyRangeHzEditField.Value,app.UIAxes,app.UIAxes_2,app.TextArea,'All',app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SelectedEvents,app.Mainapp.ActiveChannel,app.Mainapp.PlotAppearance,app.DataToExtractFromDropDown.Value,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField.Value);
        
        elseif strcmp(app.Mainapp.PlotAppearance.SpectrumWindow.PlotType,"Band Power Across Grid")  

            Freqs = str2double(strsplit(app.FrequencyRangeHzEditField.Value,','));
            [~,FreqsIndicie(1)] = min(abs(app.BandPower.F - Freqs(1)));
            [~,FreqsIndicie(2)] = min(abs(app.BandPower.F - Freqs(2)));

            EventRelatedSpectrum = app.BandPower.allPowerEst(:,FreqsIndicie(1):FreqsIndicie(2));

            [Channelrange] = Organize_Convert_ActiveChannel_to_DataChannel(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel,app.Mainapp.ActiveChannel,'MainPlot');
            EventRelatedSpectrum = EventRelatedSpectrum(Channelrange,:);
            
            Ylims = [min(EventRelatedSpectrum,[],'all') max(EventRelatedSpectrum,[],'all')];

            % EventRelatedData is channel by time
            [~,app.Mainapp.CurrentPlotData] = Module_Main_Window_Plot_Grid_Trace_View(app,app.Mainapp.Data,EventRelatedSpectrum,app.BandPower.F(FreqsIndicie(1):FreqsIndicie(2))',app.BaselineNormalizeCheckBox.Value,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.Mainapp.PreservePlotChannelLocations,[],app.Mainapp.PlotAppearance.SpectrumWindow.GridPlotType,0,Ylims,app.Mainapp.CurrentPlotData);

        end
        end

        % Menu selected function: matMenu_2
        function matMenu_2Selected(app, event)
            if ~isfield(app.Mainapp.CurrentPlotData,'SpectrumGridType')
                msgbox("Please plot grid view first!")
                return;
            end

            TimeRangePlot = str2double(app.Mainapp.TimeRangeViewBox.Value(1:end-1));
            
            if strcmp(app.Mainapp.DropDown.Value,"Preprocessed")
                if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                else
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                end
            else
                StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
            end

           
            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Event Related Spectrum over Grid",0);

        end

        % Menu selected function: txtMenu_2
        function txtMenu_2Selected(app, event)
            if ~isfield(app.Mainapp.CurrentPlotData,'SpectrumGridType')
                msgbox("Please plot grid view first!")
                return;
            end
            TimeRangePlot = str2double(app.Mainapp.TimeRangeViewBox.Value(1:end-1));
            
            if strcmp(app.Mainapp.DropDown.Value,"Preprocessed")
                if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                else
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                end
            else
                StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
            end

           
            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Event Related Spectrum over Grid",0);
        end

        % Menu selected function: xlsxMenu_2
        function xlsxMenu_2Selected(app, event)
            if ~isfield(app.Mainapp.CurrentPlotData,'SpectrumGridType')
                msgbox("Please plot grid view first!")
                return;
            end
            TimeRangePlot = str2double(app.Mainapp.TimeRangeViewBox.Value(1:end-1));
            
            if strcmp(app.Mainapp.DropDown.Value,"Preprocessed")
                if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                else
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                end
            else
                StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
            end

           
            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".xlsx",app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Event Related Spectrum over Grid",0);
        end

        % Menu selected function: LeftPlotMenu
        function LeftPlotMenuSelected(app, event)
            [file, path] = uiputfile('*.svg', 'Save as');
            
            exportgraphics(app.UIAxes, fullfile(path,file), 'ContentType', 'vector');
        end

        % Menu selected function: RightPlotMenu
        function RightPlotMenuSelected(app, event)
            [file, path] = uiputfile('*.svg', 'Save as');
            
            exportgraphics(app.UIAxes_2, fullfile(path,file), 'ContentType', 'vector');
        end

        % Menu selected function: LeftPlotMenu_2
        function LeftPlotMenu_2Selected(app, event)
            [file, path] = uiputfile('*.eps', 'Save as');
            
            exportgraphics(app.UIAxes, fullfile(path,file), 'ContentType', 'vector');
        end

        % Menu selected function: RightPlotMenu_2
        function RightPlotMenu_2Selected(app, event)
            [file, path] = uiputfile('*.eps', 'Save as');
            
            exportgraphics(app.UIAxes_2, fullfile(path,file), 'ContentType', 'vector');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EventStaticSpectrumWindowUIFigure = uifigure('Visible', 'off');
            app.EventStaticSpectrumWindowUIFigure.Name = 'Event Static Spectrum Window';
            app.EventStaticSpectrumWindowUIFigure.Icon = 'Logo.png';
            app.EventStaticSpectrumWindowUIFigure.CloseRequestFcn = createCallbackFcn(app, @EventStaticSpectrumWindowUIFigureCloseRequest, true);
            app.SnapshotMenu = uimenu(app.EventStaticSpectrumWindowUIFigure);
            app.SnapshotMenu.Text = '   Snapshot   ';
            app.SaveSnapshotofWindowMenu = uimenu(app.SnapshotMenu);
            app.SaveSnapshotofWindowMenu.Text = 'Save Snapshot of Window';
            app.PNGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.PNGMenu.MenuSelectedFcn = createCallbackFcn(app, @PNGMenuSelected, true);
            app.PNGMenu.Text = 'PNG';
            app.JPGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.JPGMenu.MenuSelectedFcn = createCallbackFcn(app, @JPGMenuSelected, true);
            app.JPGMenu.Text = 'JPG';
            app.SVGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.SVGMenu.MenuSelectedFcn = createCallbackFcn(app, @SVGMenuSelected, true);
            app.SVGMenu.Text = 'SVG';
            app.LeftPlotMenu = uimenu(app.SVGMenu);
            app.LeftPlotMenu.MenuSelectedFcn = createCallbackFcn(app, @LeftPlotMenuSelected, true);
            app.LeftPlotMenu.Text = 'Left Plot';
            app.RightPlotMenu = uimenu(app.SVGMenu);
            app.RightPlotMenu.MenuSelectedFcn = createCallbackFcn(app, @RightPlotMenuSelected, true);
            app.RightPlotMenu.Text = 'Right Plot';
            app.EPSMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.EPSMenu.Text = 'EPS';
            app.LeftPlotMenu_2 = uimenu(app.EPSMenu);
            app.LeftPlotMenu_2.MenuSelectedFcn = createCallbackFcn(app, @LeftPlotMenu_2Selected, true);
            app.LeftPlotMenu_2.Text = 'Left Plot';
            app.RightPlotMenu_2 = uimenu(app.EPSMenu);
            app.RightPlotMenu_2.MenuSelectedFcn = createCallbackFcn(app, @RightPlotMenu_2Selected, true);
            app.RightPlotMenu_2.Text = 'Right Plot';
            app.PlotMenu_2 = uimenu(app.EventStaticSpectrumWindowUIFigure);
            app.PlotMenu_2.Text = '   Plot   ';
            app.ChangePlotTypeMenu = uimenu(app.PlotMenu_2);
            app.ChangePlotTypeMenu.Text = 'Change Plot Type';
            app.IndividualChannelViewMenu = uimenu(app.ChangePlotTypeMenu);
            app.IndividualChannelViewMenu.MenuSelectedFcn = createCallbackFcn(app, @IndividualChannelViewMenuSelected, true);
            app.IndividualChannelViewMenu.Text = 'Individual Channel View';
            app.DepthViewMenu = uimenu(app.ChangePlotTypeMenu);
            app.DepthViewMenu.Text = 'Depth View';
            app.Show2DimensionalPlotMenu = uimenu(app.DepthViewMenu);
            app.Show2DimensionalPlotMenu.MenuSelectedFcn = createCallbackFcn(app, @Show2DimensionalPlotMenuSelected, true);
            app.Show2DimensionalPlotMenu.Text = 'Show 2 Dimensional Plot';
            app.Show3DimensionalPlotMenu = uimenu(app.DepthViewMenu);
            app.Show3DimensionalPlotMenu.MenuSelectedFcn = createCallbackFcn(app, @Show3DimensionalPlotMenuSelected, true);
            app.Show3DimensionalPlotMenu.Text = 'Show 3 Dimensional Plot';
            app.GridTraceViewMenu = uimenu(app.ChangePlotTypeMenu);
            app.GridTraceViewMenu.Text = 'Grid Trace View';
            app.LinesMenu = uimenu(app.GridTraceViewMenu);
            app.LinesMenu.MenuSelectedFcn = createCallbackFcn(app, @LinesMenuSelected, true);
            app.LinesMenu.Text = 'Lines';
            app.ColorplotMenu = uimenu(app.GridTraceViewMenu);
            app.ColorplotMenu.MenuSelectedFcn = createCallbackFcn(app, @ColorplotMenuSelected, true);
            app.ColorplotMenu.Text = 'Colorplot';
            app.ChangePlotPropertiesMenu = uimenu(app.PlotMenu_2);
            app.ChangePlotPropertiesMenu.Text = 'Change Plot Properties';
            app.DataPlotMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.DataPlotMenu.Text = 'Data Plot';
            app.Menu = uimenu(app.DataPlotMenu);
            app.Menu.Text = 'Menu';
            app.FontSizeMenu = uimenu(app.DataPlotMenu);
            app.FontSizeMenu.MenuSelectedFcn = createCallbackFcn(app, @FontSizeMenuSelected, true);
            app.FontSizeMenu.Text = 'FontSize';
            app.LineWidthDataMenu = uimenu(app.DataPlotMenu);
            app.LineWidthDataMenu.MenuSelectedFcn = createCallbackFcn(app, @LineWidthDataMenuSelected, true);
            app.LineWidthDataMenu.Text = 'LineWidth Data';
            app.ColorDataMenu = uimenu(app.DataPlotMenu);
            app.ColorDataMenu.MenuSelectedFcn = createCallbackFcn(app, @ColorDataMenuSelected, true);
            app.ColorDataMenu.Text = 'Color Data';
            app.BackgorundColorMenu = uimenu(app.DataPlotMenu);
            app.BackgorundColorMenu.MenuSelectedFcn = createCallbackFcn(app, @BackgorundColorMenuSelected, true);
            app.BackgorundColorMenu.Text = 'Backgorund Color';
            app.XLabelMenu = uimenu(app.DataPlotMenu);
            app.XLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @XLabelMenuSelected, true);
            app.XLabelMenu.Text = 'XLabel';
            app.YLabelMenu = uimenu(app.DataPlotMenu);
            app.YLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @YLabelMenuSelected, true);
            app.YLabelMenu.Text = 'YLabel';
            app.RESETMenu = uimenu(app.DataPlotMenu);
            app.RESETMenu.MenuSelectedFcn = createCallbackFcn(app, @RESETMenuSelected, true);
            app.RESETMenu.Text = 'RESET';
            app.GridViewPlotMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.GridViewPlotMenu.Text = 'Grid View Plot';
            app.LineWidthDataMenu_2 = uimenu(app.GridViewPlotMenu);
            app.LineWidthDataMenu_2.MenuSelectedFcn = createCallbackFcn(app, @LineWidthDataMenu_2Selected, true);
            app.LineWidthDataMenu_2.Text = 'LineWidth Data';
            app.ColorDataMenu_2 = uimenu(app.GridViewPlotMenu);
            app.ColorDataMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ColorDataMenu_2Selected, true);
            app.ColorDataMenu_2.Text = 'Color Data';
            app.RESETMenu_2 = uimenu(app.GridViewPlotMenu);
            app.RESETMenu_2.MenuSelectedFcn = createCallbackFcn(app, @RESETMenu_2Selected, true);
            app.RESETMenu_2.Text = 'RESET';
            app.SaveCustomeAppearanceMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.SaveCustomeAppearanceMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveCustomeAppearanceMenuSelected, true);
            app.SaveCustomeAppearanceMenu.Text = 'Save Custome Appearance';
            app.DeleteCustomeAppearanceMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.DeleteCustomeAppearanceMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteCustomeAppearanceMenuSelected, true);
            app.DeleteCustomeAppearanceMenu.Text = 'Delete Custome Appearance';
            app.ExportDataMenu = uimenu(app.EventStaticSpectrumWindowUIFigure);
            app.ExportDataMenu.Text = '   Export Data   ';
            app.SpectrumIndividualChannelMenu = uimenu(app.ExportDataMenu);
            app.SpectrumIndividualChannelMenu.Text = 'Spectrum Individual Channel';
            app.matMenu_3 = uimenu(app.SpectrumIndividualChannelMenu);
            app.matMenu_3.MenuSelectedFcn = createCallbackFcn(app, @matMenuSelected, true);
            app.matMenu_3.Text = '.mat';
            app.txtMenu_3 = uimenu(app.SpectrumIndividualChannelMenu);
            app.txtMenu_3.MenuSelectedFcn = createCallbackFcn(app, @txtMenuSelected, true);
            app.txtMenu_3.Text = '.txt';
            app.xlsxMenu_3 = uimenu(app.SpectrumIndividualChannelMenu);
            app.xlsxMenu_3.MenuSelectedFcn = createCallbackFcn(app, @xlsxMenuSelected, true);
            app.xlsxMenu_3.Text = '.xlsx';
            app.GridERPMenu = uimenu(app.ExportDataMenu);
            app.GridERPMenu.Text = 'Grid ERP';
            app.matMenu_2 = uimenu(app.GridERPMenu);
            app.matMenu_2.MenuSelectedFcn = createCallbackFcn(app, @matMenu_2Selected, true);
            app.matMenu_2.Text = '.mat';
            app.txtMenu_2 = uimenu(app.GridERPMenu);
            app.txtMenu_2.MenuSelectedFcn = createCallbackFcn(app, @txtMenu_2Selected, true);
            app.txtMenu_2.Text = '.txt';
            app.xlsxMenu_2 = uimenu(app.GridERPMenu);
            app.xlsxMenu_2.MenuSelectedFcn = createCallbackFcn(app, @xlsxMenu_2Selected, true);
            app.xlsxMenu_2.Text = '.xlsx';
            app.UIAxes = uiaxes(app.EventStaticSpectrumWindowUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.EventStaticSpectrumWindowUIFigure);
            xlabel(app.UIAxes_2, 'X')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.YTick = [];
            app.Grid_Traces_View_Panel = uipanel(app.EventStaticSpectrumWindowUIFigure);
            app.AnalysisSettingsPanel = uipanel(app.EventStaticSpectrumWindowUIFigure);
            app.AnalysisSettingsPanel.Title = 'Analysis Settings';
            app.MethodLabel = uilabel(app.AnalysisSettingsPanel);
            app.MethodLabel.Text = 'Method';
            app.DataTypeDropDown = uidropdown(app.AnalysisSettingsPanel);
            app.DataTypeDropDown.Items = {'Channel Individually', 'Mean over all Channel'};
            app.DataTypeDropDown.DropDownOpeningFcn = createCallbackFcn(app, @DataTypeDropDownOpening, true);
            app.DataTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DataTypeDropDownValueChanged, true);
            app.DataTypeDropDown.Value = 'Channel Individually';
            app.TriggerNumberSelectionLabel = uilabel(app.AnalysisSettingsPanel);
            app.TriggerNumberSelectionLabel.Text = {'Event Trigger '; 'Selection (Matlab '; 'Expressions)'};
            app.EventSelectionEditField = uieditfield(app.AnalysisSettingsPanel, 'text');
            app.EventSelectionEditField.ValueChangedFcn = createCallbackFcn(app, @EventSelectionEditFieldValueChanged, true);
            app.AnalysisSettingsLabel = uilabel(app.AnalysisSettingsPanel);
            app.AnalysisSettingsLabel.Text = 'Analysis Settings';
            app.EventChannelSelectionDropDownLabel = uilabel(app.AnalysisSettingsPanel);
            app.EventChannelSelectionDropDownLabel.Text = 'Event Channel Selection';
            app.EventTriggerChannel = uidropdown(app.AnalysisSettingsPanel);
            app.EventTriggerChannel.Items = {};
            app.EventTriggerChannel.ValueChangedFcn = createCallbackFcn(app, @EventTriggerChannelValueChanged, true);
            app.EventTriggerChannel.Value = {};
            app.DataToExtractFromDropDownLabel = uilabel(app.AnalysisSettingsPanel);
            app.DataToExtractFromDropDownLabel.Text = 'Data To Extract From';
            app.DataToExtractFromDropDown = uidropdown(app.AnalysisSettingsPanel);
            app.DataToExtractFromDropDown.Items = {};
            app.DataToExtractFromDropDown.ValueChangedFcn = createCallbackFcn(app, @DataToExtractFromDropDownValueChanged, true);
            app.DataToExtractFromDropDown.Value = {};
            app.EventDataTypeLabel = uilabel(app.AnalysisSettingsPanel);
            app.EventDataTypeLabel.Text = 'Event Data Type';
            app.DataSourceDropDown = uidropdown(app.AnalysisSettingsPanel);
            app.DataSourceDropDown.Items = {'Raw Event Related Data'};
            app.DataSourceDropDown.ValueChangedFcn = createCallbackFcn(app, @DataSourceDropDownValueChanged, true);
            app.DataSourceDropDown.Value = 'Raw Event Related Data';
            app.BaselineNormalizeCheckBox = uicheckbox(app.AnalysisSettingsPanel);
            app.BaselineNormalizeCheckBox.ValueChangedFcn = createCallbackFcn(app, @BaselineNormalizeCheckBoxValueChanged, true);
            app.BaselineNormalizeCheckBox.Text = {'   Baseline '; '   Normalize'};
            app.TriggerNumberSelectionMatlabExpressionsLabel = uilabel(app.AnalysisSettingsPanel);
            app.TriggerNumberSelectionMatlabExpressionsLabel.Text = {'Baseline Window'; '[from, to] in s'};
            app.EventNumberSelectionEditField = uieditfield(app.AnalysisSettingsPanel, 'text');
            app.EventNumberSelectionEditField.ValueChangedFcn = createCallbackFcn(app, @EventNumberSelectionEditFieldValueChanged, true);
            app.EventNumberSelectionEditField_2 = uieditfield(app.AnalysisSettingsPanel, 'text');
            app.TriggerNumberSelectionMatlabExpressionsLabel_2 = uilabel(app.AnalysisSettingsPanel);
            app.TriggerNumberSelectionMatlabExpressionsLabel_2.Text = 'Total Nr Of Trigger';
            app.ChannelSelectionDropDownLabel = uilabel(app.AnalysisSettingsPanel);
            app.ChannelSelectionDropDownLabel.Text = 'Channel Selection';
            app.ChannelDropDown = uidropdown(app.AnalysisSettingsPanel);
            app.ChannelDropDown.Items = {};
            app.ChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelDropDownValueChanged, true);
            app.ChannelDropDown.Value = {};
            app.FrequencyRangeHzfromtoEditFieldLabel = uilabel(app.AnalysisSettingsPanel);
            app.FrequencyRangeHzfromtoEditFieldLabel.Text = {'Frequency'; 'Range [Hz]'; '(from,to)'};
            app.FrequencyRangeHzEditField = uieditfield(app.AnalysisSettingsPanel, 'text');
            app.FrequencyRangeHzEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyRangeHzEditFieldValueChanged, true);
            app.FrequencyRangeHzEditField.Enable = 'off';
            app.FrequencyRangeHzEditField.Value = '0,80';
            app.TextArea_2 = uitextarea(app.EventStaticSpectrumWindowUIFigure);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Enable = 'off';
            app.TextArea_2.Visible = 'off';
            app.CloseButton = uibutton(app.EventStaticSpectrumWindowUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.Button = uibutton(app.EventStaticSpectrumWindowUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'Open_Figure_Icon.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.EventStaticSpectrumWindowUIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'Open_Figure_Icon.png';
            app.Button_2.Text = '';
            % Show the figure after all components are created
            app.EventStaticSpectrumWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Event_Static_Power_Spectrum_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.EventStaticSpectrumWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.EventStaticSpectrumWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EventStaticSpectrumWindowUIFigure)
        end
    end
end