classdef Time_Frequency_Power_Window_Spectrogram < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TFAnalysisUIFigure              matlab.ui.Figure
        SnapshotMenu                    matlab.ui.container.Menu
        SaveSnapshotofWindowMenu        matlab.ui.container.Menu
        PNGMenu                         matlab.ui.container.Menu
        JPGMenu                         matlab.ui.container.Menu
        SVGMenu                         matlab.ui.container.Menu
        PlotMenu                        matlab.ui.container.Menu
        Show2DimensionalPlotsMenu       matlab.ui.container.Menu
        Show3DimensionalPlotsMenu       matlab.ui.container.Menu
        TFPlotMenu                      matlab.ui.container.Menu
        FontSizeMenu                    matlab.ui.container.Menu
        LineWidthTriggerMenu            matlab.ui.container.Menu
        ColorTriggerMenu                matlab.ui.container.Menu
        BackgorundColorMenu             matlab.ui.container.Menu
        XLabelMenu                      matlab.ui.container.Menu
        YLabelMenu                      matlab.ui.container.Menu
        CLabelMenu                      matlab.ui.container.Menu
        RESETMenu                       matlab.ui.container.Menu
        SaveCostumeAppearanceMenu       matlab.ui.container.Menu
        DeleteCostumeAppearanceMenu     matlab.ui.container.Menu
        ExportDataMenu                  matlab.ui.container.Menu
        matMenu                         matlab.ui.container.Menu
        txtMenu                         matlab.ui.container.Menu
        xlsxMenu                        matlab.ui.container.Menu
        RightPanel                      matlab.ui.container.Panel
        Button                          matlab.ui.control.Button
        ChannelSelectionDropDown        matlab.ui.control.EditField
        ChannelLabel                    matlab.ui.control.Label
        AnalysisParameterPanel          matlab.ui.container.Panel
        SmoothingEditField_2            matlab.ui.control.EditField
        WindowSizeSamplesLabel_2        matlab.ui.control.Label
        TriggerToComparecommaseparatedLabel  matlab.ui.control.Label
        FrequencyRangeminmaxstepsEditField_2  matlab.ui.control.EditField
        CoherencebetweenChannelCheckBox  matlab.ui.control.CheckBox
        CoherencebetweenTrialsCheckBox  matlab.ui.control.CheckBox
        DataSourceDropDown_3            matlab.ui.control.DropDown
        ChannelToAnalyzeLabel_2         matlab.ui.control.Label
        DataSourceDropDown_2            matlab.ui.control.DropDown
        ChannelToAnalyzeLabel           matlab.ui.control.Label
        AnalysisParameterLabel          matlab.ui.control.Label
        ClimminmaxEditField             matlab.ui.control.EditField
        ClimminmaxLabel                 matlab.ui.control.Label
        AutoClimButton                  matlab.ui.control.Button
        FrequencyRangeminmaxstepsEditField  matlab.ui.control.EditField
        FrequRangeminstepsmaxLabel      matlab.ui.control.Label
        CycleWidthfromto23EditField     matlab.ui.control.EditField
        WindowSizeSamplesLabel          matlab.ui.control.Label
        CloseButton                     matlab.ui.control.Button
        EventandChannelSelectionPanel   matlab.ui.container.Panel
        EventNumberSelectionEditField_3  matlab.ui.control.EditField
        TriggerNumberSelectionMatlabExpressionsLabel_3  matlab.ui.control.Label
        BaselineNormalizeCheckBox       matlab.ui.control.CheckBox
        TriggerNumberSelectionMatlabExpressionsLabel_2  matlab.ui.control.Label
        EventNumberSelectionEditField_2  matlab.ui.control.EditField
        EventandChannelSelectionLabel   matlab.ui.control.Label
        DataToExtractFromDropDown       matlab.ui.control.DropDown
        DataToExtractFromDropDownLabel  matlab.ui.control.Label
        EventTriggerChannel             matlab.ui.control.DropDown
        EventChannelSelectionDropDownLabel  matlab.ui.control.Label
        DataSourceDropDown              matlab.ui.control.DropDown
        EventDataTypeDropDownLabel      matlab.ui.control.Label
        EventNumberSelectionEditField   matlab.ui.control.EditField
        TriggerNumberSelectionMatlabExpressionsLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        LeftPanel                       matlab.ui.container.Panel
    end

    
    properties (Access = public)
        EventRelatedWindow = []; % Description
        climsTF = [];
        PlotSelection = [];
        TFInfos = [];
        TwoORThreeD = "TwoD"; % standard 2d plots when possible
        Mainapp = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, EventRelatedWindow, Mainapp)
            movegui(app.TFAnalysisUIFigure, 'center');
            
            app.Mainapp = Mainapp;

            app = Utility_Change_Light_Dark_Mode(app,"EventTF");

            %% -------------------- Set UP Window General things-------------------- 
            if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                app.DataSourceDropDown.Items{1} = 'Raw Event Related Data';
                app.DataSourceDropDown.Items{2} = 'Preprocessed Event Related Data';
            else
                app.DataSourceDropDown.Items{1} = 'Raw Event Related Data';
            end

            if isfield(app.Mainapp.Data,'Preprocessed')
                app.DataToExtractFromDropDown.Items{1} = 'Raw Data';
                app.DataToExtractFromDropDown.Items{2} = 'Preprocessed Data';
            else
                app.DataToExtractFromDropDown.Items{1} = 'Raw Data';
            end

            for i = 1:length(app.Mainapp.Data.Info.EventChannelNames)
                app.EventTriggerChannel.Items{i} = convertStringsToChars(app.Mainapp.Data.Info.EventChannelNames{i});
            end
                        
            app.CoherencebetweenChannelCheckBox.Value = 0;
            app.CoherencebetweenTrialsCheckBox.Value = 0;
            app.CoherencebetweenTrialsCheckBox.Enable = "off";
            app.CoherencebetweenChannelCheckBox.Enable = "off";
            app.FrequencyRangeminmaxstepsEditField_2.Enable = "off";
            app.SmoothingEditField_2.Enable = "off";

            % Extract Cell with eventindicies of selected event channel
            spaceindicie = find(app.Mainapp.Data.Info.EventRelatedDataTimeRange == ' ');
            app.TFInfos.Timearoundevent(1) = str2double(app.Mainapp.Data.Info.EventRelatedDataTimeRange(1:spaceindicie(1)));
            app.TFInfos.Timearoundevent(2) = str2double(app.Mainapp.Data.Info.EventRelatedDataTimeRange(spaceindicie(1)+1:end));

            %% -------------------- Check if prepro event related data found and can be offered -------------------- 
            [texttoshow,Value] = Event_Module_Check_Event_Preprocessing(app.Mainapp.Data,app.EventTriggerChannel.Value,"Raw Event Related Data");
            app.DataSourceDropDown.Items = {};
            app.DataSourceDropDown.Items = texttoshow;
            app.DataSourceDropDown.Value = Value;
            
            %% -------------------- Extract Event Related Data -------------------- 
            [app.Mainapp.Data,~] = Event_Module_Extract_Event_Related_Data(app.Mainapp.Data,app.EventTriggerChannel.Value,app.TFInfos.Timearoundevent,app.DataToExtractFromDropDown.Value,app.DataSourceDropDown.Value);
            
            %% -------------------- Set Basline Normlaization and trigger nhumber -------------------- 
            EventRange = str2double(strsplit(app.Mainapp.Data.Info.EventRelatedDataTimeRange,' '));
            app.EventNumberSelectionEditField_3.Value = strcat('-',num2str(EventRange(1)),',0');
            
            if strcmp(app.DataSourceDropDown.Value,'Raw Event Related Data')
                app.EventNumberSelectionEditField_2.Value = strcat(num2str(size(app.Mainapp.Data.EventRelatedData,2)),' Trigger');
            else
                app.EventNumberSelectionEditField_2.Value = strcat(num2str(size(app.Mainapp.Data.PreprocessedEventRelatedData,2)),' Trigger');
            end

            %% -------------------- Set UP Channel Selection -------------------- 

            AllChannels = {};
            if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 1
                TempActiveChannel = (str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)*str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)+1)-sort(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
                for i = 1:size(app.Mainapp.Data.Raw,1)  
                    AllChannels{i} = num2str(TempActiveChannel(i));
                end
            else
                for i = 1:size(app.Mainapp.Data.Raw,1)
                    AllChannels{i} = num2str(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel(i));
                end
            end

            app.DataSourceDropDown_2.Items = AllChannels;
                        
            %% -------------------- Populate event channel selection -------------------- 
            [app.EventNumberSelectionEditField.Value] = Event_Module_Check_EventInput(app.EventNumberSelectionEditField.Value,app.Mainapp.Data,app.EventTriggerChannel.Value,app.DataSourceDropDown.Value,1);
            
            %% -------------------- PLOT -------------------- 
            [TF] = Event_Module_Organize_TF_Window_Inputs(app.Mainapp.Data,app.DataSourceDropDown_2.Value,app.DataSourceDropDown_3.Value,app.Mainapp.ActiveChannel,app.EventNumberSelectionEditField.Value,app.FrequencyRangeminmaxstepsEditField.Value,app.CycleWidthfromto23EditField.Value,app.FrequencyRangeminmaxstepsEditField_2.Value,app.CoherencebetweenTrialsCheckBox.Value,app.CoherencebetweenChannelCheckBox.Value,app.SmoothingEditField_2.Value);
            
            %(Data,EventRelatedData,Figure,SampleRate,EventNrRange,TimearoundEvent,TF,Window,TwoORThreeD,CurrentPlotData,PlotAppearance,BaselineNormalize,NormalizationWindow)
            if strcmp(app.DataToExtractFromDropDown.Value,'Raw Data')                
                if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                end
            else
                if isfield(app.Mainapp.Data.Info,"DownsampleFactor")
                    if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    end
                else
                    if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    end
                end
            end

            app = Utility_Change_Light_Dark_Mode(app,"EventTF");
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.Mainapp.EventLFPTF = [];
            
            try
                if ~isempty(app.Mainapp.ProbeViewWindowHandle) % delete option from probe view 
                    for i = 1:length(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                        if strcmp(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Event Time Frequency Power Window')
                            app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items(i) = [];
                            break;
                        end
                    end
                else % if no probe view available
     
                end

            end

            delete(app);
        end

        % Value changed function: EventNumberSelectionEditField
        function EventNumberSelectionEditFieldValueChanged(app, event)
            %% -------------------- Populate event channel selection -------------------- 
            [app.EventNumberSelectionEditField.Value] = Event_Module_Check_EventInput(app.EventNumberSelectionEditField.Value,app.Mainapp.Data,app.EventTriggerChannel.Value,app.DataSourceDropDown.Value,0);
            
            %% -------------------- PLOT -------------------- 
            [TF] = Event_Module_Organize_TF_Window_Inputs(app.Mainapp.Data,app.DataSourceDropDown_2.Value,app.DataSourceDropDown_3.Value,app.Mainapp.ActiveChannel,app.EventNumberSelectionEditField.Value,app.FrequencyRangeminmaxstepsEditField.Value,app.CycleWidthfromto23EditField.Value,app.FrequencyRangeminmaxstepsEditField_2.Value,app.CoherencebetweenTrialsCheckBox.Value,app.CoherencebetweenChannelCheckBox.Value,app.SmoothingEditField_2.Value);
            
            if strcmp(app.DataToExtractFromDropDown.Value,'Raw Data')           
                if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                end
            else
                if isfield(app.Mainapp.Data.Info,"DownsampleFactor")
                    if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    end
                else
                    if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    end
                end
            end

            cb = colorbar(app.UIAxes);
            cb.Color = 'k';              % Sets tick mark and label color to black
            cb.Label.String = app.Mainapp.PlotAppearance.TFWindow.CLabel;
            cb.Label.Rotation = 270;
            %cb.FontSize =  app.Mainapp.PlotAppearance.TFWindow.FontSize;  
            cb.Label.Color = 'k';        % Sets the color of the label text
        end

        % Value changed function: ClimminmaxEditField
        function ClimminmaxEditFieldValueChanged(app, event)
            %[app.ClimminmaxEditField.Value] = Utility_SimpleCheckInputs(app.ClimminmaxEditField.Value,"Two",strcat('1,',num2str(size(app.Mainapp.Data.EventRelatedData,1))),0,1);
            
            value = app.ClimminmaxEditField.Value;
            if ~isempty(value)
                indicesep = find(value == ',');
                climcostumTF(1,1) = str2double(value(1:indicesep-1));
                climcostumTF(1,2) = str2double(value(indicesep+1:end));
    
                app.UIAxes.CLim = climcostumTF;

            end
        end

        % Button pushed function: AutoClimButton
        function AutoClimButtonPushed(app, event)
            if ~isempty(app.climsTF)
                app.UIAxes.CLim = app.climsTF;
            end
        end

        % Menu selected function: PNGMenu
        function PNGMenuSelected(app, event)
            [file, path] = uiputfile('*.png', 'Save as');

            exportapp(app.TFAnalysisUIFigure,fullfile(path,file)) 
        end

        % Menu selected function: JPGMenu
        function JPGMenuSelected(app, event)
            [file, path] = uiputfile('*.jpg', 'Save as');

            exportapp(app.TFAnalysisUIFigure,fullfile(path,file)) 
        end

        % Value changed function: ChannelSelectionDropDown
        function ChannelSelectionDropDownValueChanged(app, event)
            [TF] = Event_Module_Organize_TF_Window_Inputs(app.Mainapp.Data,app.DataSourceDropDown_2.Value,app.DataSourceDropDown_3.Value,app.Mainapp.ActiveChannel,app.EventNumberSelectionEditField.Value,app.FrequencyRangeminmaxstepsEditField.Value,app.CycleWidthfromto23EditField.Value,app.FrequencyRangeminmaxstepsEditField_2.Value,app.CoherencebetweenTrialsCheckBox.Value,app.CoherencebetweenChannelCheckBox.Value,app.SmoothingEditField_2.Value);

            if strcmp(app.DataToExtractFromDropDown.Value,'Raw Data')           
                if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                end
            else
                if isfield(app.Mainapp.Data.Info,"DownsampleFactor")
                    if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    end
                else
                    if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    end
                end
            end
        end

        % Value changed function: FrequencyRangeminmaxstepsEditField
        function FrequencyRangeminmaxstepsEditFieldValueChanged(app, event)
            Freqs = str2double(strsplit(app.FrequencyRangeminmaxstepsEditField.Value,','));
            % if strcmp(app.DataSourceDropDown_3.Value,"Time Varying Wavelet Coherence")
            %     if Freqs(2)>32 && strcmp(app.DataSourceDropDown_3.Value,"Time Varying Wavelet Coherence")
            %         msgbox("Only 32 steps allowed for Wavelet Coherence. Auto-changing frequency range.");
            %         Freqs(2) = 32;
            %         app.FrequencyRangeminmaxstepsEditField.Value = strjoin(string(Freqs),',');
            %     end
            % end

            [TF] = Event_Module_Organize_TF_Window_Inputs(app.Mainapp.Data,app.DataSourceDropDown_2.Value,app.DataSourceDropDown_3.Value,app.Mainapp.ActiveChannel,app.EventNumberSelectionEditField.Value,app.FrequencyRangeminmaxstepsEditField.Value,app.CycleWidthfromto23EditField.Value,app.FrequencyRangeminmaxstepsEditField_2.Value,app.CoherencebetweenTrialsCheckBox.Value,app.CoherencebetweenChannelCheckBox.Value,app.SmoothingEditField_2.Value);

            if strcmp(app.DataToExtractFromDropDown.Value,'Raw Data')           
                if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                end
            else
                if isfield(app.Mainapp.Data.Info,"DownsampleFactor")
                    if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    end
                else
                    if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    end
                end
            end

            cb = colorbar(app.UIAxes);
            cb.Color = 'k';              % Sets tick mark and label color to black
            cb.Label.String = app.Mainapp.PlotAppearance.TFWindow.CLabel;
            cb.Label.Rotation = 270;
            %cb.FontSize =  app.Mainapp.PlotAppearance.TFWindow.FontSize;  
            cb.Label.Color = 'k';        % Sets the color of the label text
        end

        % Value changed function: CycleWidthfromto23EditField
        function CycleWidthfromto23EditFieldValueChanged(app, event)
            [app.CycleWidthfromto23EditField.Value] = Utility_SimpleCheckInputs(app.CycleWidthfromto23EditField.Value,"One",'1000',0,0);

            [TF] = Event_Module_Organize_TF_Window_Inputs(app.Mainapp.Data,app.DataSourceDropDown_2.Value,app.DataSourceDropDown_3.Value,app.Mainapp.ActiveChannel,app.EventNumberSelectionEditField.Value,app.FrequencyRangeminmaxstepsEditField.Value,app.CycleWidthfromto23EditField.Value,app.FrequencyRangeminmaxstepsEditField_2.Value,app.CoherencebetweenTrialsCheckBox.Value,app.CoherencebetweenChannelCheckBox.Value,app.SmoothingEditField_2.Value);

            if strcmp(app.DataToExtractFromDropDown.Value,'Raw Data')           
                if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                end
            else
                if isfield(app.Mainapp.Data.Info,"DownsampleFactor")
                    if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    end
                else
                    if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    end
                end
            end

            cb = colorbar(app.UIAxes);
            cb.Color = 'k';              % Sets tick mark and label color to black
            cb.Label.String = app.Mainapp.PlotAppearance.TFWindow.CLabel;
            cb.Label.Rotation = 270;
            %cb.FontSize =  app.Mainapp.PlotAppearance.TFWindow.FontSize;  
            cb.Label.Color = 'k';        % Sets the color of the label text
        end

        % Value changed function: DataSourceDropDown
        function DataSourceDropDownValueChanged(app, event)
            %% -------------------- Extract Event Related Data -------------------- 
            [app.Mainapp.Data,~] = Event_Module_Extract_Event_Related_Data(app.Mainapp.Data,app.EventTriggerChannel.Value,app.TFInfos.Timearoundevent,app.DataToExtractFromDropDown.Value,app.DataSourceDropDown.Value);
            
            %% -------------------- Set Basline Normlaization and trigger number -------------------- 
            if strcmp(app.DataSourceDropDown.Value,'Raw Event Related Data')
                app.EventNumberSelectionEditField_2.Value = strcat(num2str(size(app.Mainapp.Data.EventRelatedData,2)),' Trigger');
            else
                app.EventNumberSelectionEditField_2.Value = strcat(num2str(size(app.Mainapp.Data.PreprocessedEventRelatedData,2)),' Trigger');
            end

            %% -------------------- Populate event channel selection -------------------- 
            [app.EventNumberSelectionEditField.Value] = Event_Module_Check_EventInput(app.EventNumberSelectionEditField.Value,app.Mainapp.Data,app.EventTriggerChannel.Value,app.DataSourceDropDown.Value,1);
             
            %% -------------------- Plot -------------------- 

            [TF] = Event_Module_Organize_TF_Window_Inputs(app.Mainapp.Data,app.DataSourceDropDown_2.Value,app.DataSourceDropDown_3.Value,app.Mainapp.ActiveChannel,app.EventNumberSelectionEditField.Value,app.FrequencyRangeminmaxstepsEditField.Value,app.CycleWidthfromto23EditField.Value,app.FrequencyRangeminmaxstepsEditField_2.Value,app.CoherencebetweenTrialsCheckBox.Value,app.CoherencebetweenChannelCheckBox.Value,app.SmoothingEditField_2.Value);

            if strcmp(app.DataToExtractFromDropDown.Value,'Raw Data')           
                if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                end
            else
                if isfield(app.Mainapp.Data.Info,"DownsampleFactor")
                    if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    end
                else
                    if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    end
                end
            end

            cb = colorbar(app.UIAxes);
            cb.Color = 'k';              % Sets tick mark and label color to black
            cb.Label.String = app.Mainapp.PlotAppearance.TFWindow.CLabel;
            cb.Label.Rotation = 270;
            cb.FontSize =  app.Mainapp.PlotAppearance.TFWindow.FontSize;  
            cb.Label.Color = 'k';        % Sets the color of the label text
        end

        % Menu selected function: Show2DimensionalPlotsMenu
        function Show2DimensionalPlotsMenuSelected(app, event)
            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";
            
            app.UIAxes.Color = app.Mainapp.PlotAppearance.TFWindow.BackgroundColor;
            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.TFWindow.FontSize;

            app.TwoORThreeD = "TwoD";

            [TF] = Event_Module_Organize_TF_Window_Inputs(app.Mainapp.Data,app.DataSourceDropDown_2.Value,app.DataSourceDropDown_3.Value,app.Mainapp.ActiveChannel,app.EventNumberSelectionEditField.Value,app.FrequencyRangeminmaxstepsEditField.Value,app.CycleWidthfromto23EditField.Value,app.FrequencyRangeminmaxstepsEditField_2.Value,app.CoherencebetweenTrialsCheckBox.Value,app.CoherencebetweenChannelCheckBox.Value,app.SmoothingEditField_2.Value);

            if strcmp(app.DataToExtractFromDropDown.Value,'Raw Data')           
                if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                end
            else
                if isfield(app.Mainapp.Data.Info,"DownsampleFactor")
                    if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    end
                else
                    if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    end
                end
            end
        end

        % Menu selected function: matMenu
        function matMenuSelected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;
            
            if app.TimeFrequencyCheckBox.Value == 1
                AddonName = "Time Frequency Power";
            elseif app.IntertrialPhaseClusteringCheckBox.Value == 1
                AddonName = "Intertrial Phase Clustering";
            end

            if app.PhaseindependentCheckBox.Value == 1
                AddonName = strcat("Phase Independent ",AddonName);
            elseif app.PhaselockedCheckBox.Value == 1
                AddonName = strcat("Phase Locked ",AddonName);
            elseif app.NonphaselockedCheckBox.Value == 1
                AddonName = strcat("Non Phase Locked ",AddonName);
            end

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,AddonName,0);
        end

        % Menu selected function: txtMenu
        function txtMenuSelected(app, event)
           TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;
            
            if app.TimeFrequencyCheckBox.Value == 1
                AddonName = "Time Frequency Power";
            elseif app.IntertrialPhaseClusteringCheckBox.Value == 1
                AddonName = "Intertrial Phase Clustering";
            end

            if app.PhaseindependentCheckBox.Value == 1
                AddonName = strcat("Phase Independent ",AddonName);
            elseif app.PhaselockedCheckBox.Value == 1
                AddonName = strcat("Phase Locked ",AddonName);
            elseif app.NonphaselockedCheckBox.Value == 1
                AddonName = strcat("Non Phase Locked ",AddonName);
            end

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,AddonName,0);
        end

        % Menu selected function: Show3DimensionalPlotsMenu
        function Show3DimensionalPlotsMenuSelected(app, event)
            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";

            app.UIAxes.Color = app.Mainapp.PlotAppearance.TFWindow.BackgroundColor;
            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.TFWindow.FontSize;
            
            app.TwoORThreeD = "ThreeD";

            [TF] = Event_Module_Organize_TF_Window_Inputs(app.Mainapp.Data,app.DataSourceDropDown_2.Value,app.DataSourceDropDown_3.Value,app.Mainapp.ActiveChannel,app.EventNumberSelectionEditField.Value,app.FrequencyRangeminmaxstepsEditField.Value,app.CycleWidthfromto23EditField.Value,app.FrequencyRangeminmaxstepsEditField_2.Value,app.CoherencebetweenTrialsCheckBox.Value,app.CoherencebetweenChannelCheckBox.Value,app.SmoothingEditField_2.Value);

            if strcmp(app.DataToExtractFromDropDown.Value,'Raw Data')           
                if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                end
            else
                if isfield(app.Mainapp.Data.Info,"DownsampleFactor")
                    if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    end
                else
                    if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    end
                end
            end
        end

        % Menu selected function: FontSizeMenu
        function FontSizeMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'fontsize',app.Mainapp,"TFWindowDataFontSize");
            waitfor(h);

            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.TFWindow.FontSize;
        end

        % Menu selected function: LineWidthTriggerMenu
        function LineWidthTriggerMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'linewidth',app.Mainapp,"TFEventLineWidth");
            waitfor(h);

            [TF] = Event_Module_Organize_TF_Window_Inputs(app.Mainapp.Data,app.DataSourceDropDown_2.Value,app.DataSourceDropDown_3.Value,app.Mainapp.ActiveChannel,app.EventNumberSelectionEditField.Value,app.FrequencyRangeminmaxstepsEditField.Value,app.CycleWidthfromto23EditField.Value,app.FrequencyRangeminmaxstepsEditField_2.Value,app.CoherencebetweenTrialsCheckBox.Value,app.CoherencebetweenChannelCheckBox.Value,app.SmoothingEditField_2.Value);

            if strcmp(app.DataToExtractFromDropDown.Value,'Raw Data')           
                if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                end
            else
                if isfield(app.Mainapp.Data.Info,"DownsampleFactor")
                    if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    end
                else
                    if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    end
                end
            end
        end

        % Menu selected function: ColorTriggerMenu
        function ColorTriggerMenuSelected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.Mainapp.PlotAppearance.TFWindow.TriggerColor = selectedColor;
            end

            [TF] = Event_Module_Organize_TF_Window_Inputs(app.Mainapp.Data,app.DataSourceDropDown_2.Value,app.DataSourceDropDown_3.Value,app.Mainapp.ActiveChannel,app.EventNumberSelectionEditField.Value,app.FrequencyRangeminmaxstepsEditField.Value,app.CycleWidthfromto23EditField.Value,app.FrequencyRangeminmaxstepsEditField_2.Value,app.CoherencebetweenTrialsCheckBox.Value,app.CoherencebetweenChannelCheckBox.Value,app.SmoothingEditField_2.Value);

            if strcmp(app.DataToExtractFromDropDown.Value,'Raw Data')           
                if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                end
            else
                if isfield(app.Mainapp.Data.Info,"DownsampleFactor")
                    if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    end
                else
                    if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    end
                end
            end
        end

        % Menu selected function: BackgorundColorMenu
        function BackgorundColorMenuSelected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.Mainapp.PlotAppearance.TFWindow.BackgroundColor = selectedColor;
                app.UIAxes.Color = app.Mainapp.PlotAppearance.TFWindow.BackgroundColor;
            end
        end

        % Menu selected function: XLabelMenu
        function XLabelMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'xlabel',app.Mainapp,"TFXLabel");
            waitfor(h);

            [TF] = Event_Module_Organize_TF_Window_Inputs(app.Mainapp.Data,app.DataSourceDropDown_2.Value,app.DataSourceDropDown_3.Value,app.Mainapp.ActiveChannel,app.EventNumberSelectionEditField.Value,app.FrequencyRangeminmaxstepsEditField.Value,app.CycleWidthfromto23EditField.Value,app.FrequencyRangeminmaxstepsEditField_2.Value,app.CoherencebetweenTrialsCheckBox.Value,app.CoherencebetweenChannelCheckBox.Value,app.SmoothingEditField_2.Value);

            if strcmp(app.DataToExtractFromDropDown.Value,'Raw Data')           
                if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                end
            else
                if isfield(app.Mainapp.Data.Info,"DownsampleFactor")
                    if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    end
                else
                    if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    end
                end
            end
        end

        % Menu selected function: YLabelMenu
        function YLabelMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'ylabel',app.Mainapp,"TFYLabel");
            waitfor(h);

            [TF] = Event_Module_Organize_TF_Window_Inputs(app.Mainapp.Data,app.DataSourceDropDown_2.Value,app.DataSourceDropDown_3.Value,app.Mainapp.ActiveChannel,app.EventNumberSelectionEditField.Value,app.FrequencyRangeminmaxstepsEditField.Value,app.CycleWidthfromto23EditField.Value,app.FrequencyRangeminmaxstepsEditField_2.Value,app.CoherencebetweenTrialsCheckBox.Value,app.CoherencebetweenChannelCheckBox.Value,app.SmoothingEditField_2.Value);

            if strcmp(app.DataToExtractFromDropDown.Value,'Raw Data')           
                if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                end
            else
                if isfield(app.Mainapp.Data.Info,"DownsampleFactor")
                    if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    end
                else
                    if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    end
                end
            end
        end

        % Menu selected function: CLabelMenu
        function CLabelMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'clabel',app.Mainapp,"TFCLabel");
            waitfor(h);

            [TF] = Event_Module_Organize_TF_Window_Inputs(app.Mainapp.Data,app.DataSourceDropDown_2.Value,app.DataSourceDropDown_3.Value,app.Mainapp.ActiveChannel,app.EventNumberSelectionEditField.Value,app.FrequencyRangeminmaxstepsEditField.Value,app.CycleWidthfromto23EditField.Value,app.FrequencyRangeminmaxstepsEditField_2.Value,app.CoherencebetweenTrialsCheckBox.Value,app.CoherencebetweenChannelCheckBox.Value,app.SmoothingEditField_2.Value);

            if strcmp(app.DataToExtractFromDropDown.Value,'Raw Data')           
                if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                end
            else
                if isfield(app.Mainapp.Data.Info,"DownsampleFactor")
                    if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    end
                else
                    if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    end
                end
            end
        end

        % Menu selected function: RESETMenu
        function RESETMenuSelected(app, event)
            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";

            [app.Mainapp.PlotAppearance] = Organize_Set_Standard_PlotAppearance("TFPlot",app.Mainapp.PlotAppearance);

            app.UIAxes.Color = app.Mainapp.PlotAppearance.TFWindow.BackgroundColor;
            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.TFWindow.FontSize;
            
            [TF] = Event_Module_Organize_TF_Window_Inputs(app.Mainapp.Data,app.DataSourceDropDown_2.Value,app.DataSourceDropDown_3.Value,app.Mainapp.ActiveChannel,app.EventNumberSelectionEditField.Value,app.FrequencyRangeminmaxstepsEditField.Value,app.CycleWidthfromto23EditField.Value,app.FrequencyRangeminmaxstepsEditField_2.Value,app.CoherencebetweenTrialsCheckBox.Value,app.CoherencebetweenChannelCheckBox.Value,app.SmoothingEditField_2.Value);

            if strcmp(app.DataToExtractFromDropDown.Value,'Raw Data')           
                if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                end
            else
                if isfield(app.Mainapp.Data.Info,"DownsampleFactor")
                    if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    end
                else
                    if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    end
                end
            end
        end

        % Menu selected function: SaveCostumeAppearanceMenu
        function SaveCostumeAppearanceMenuSelected(app, event)
            app.Mainapp.PlotAppearance = Utility_Save_Load_Delete_Plot_Appearance(app.Mainapp.PlotAppearance,app.Mainapp.executableFolder,"Save");
        end

        % Menu selected function: DeleteCostumeAppearanceMenu
        function DeleteCostumeAppearanceMenuSelected(app, event)
            app.Mainapp.PlotAppearance = Utility_Save_Load_Delete_Plot_Appearance(app.Mainapp.PlotAppearance,app.Mainapp.executableFolder,"Delete");
        end

        % Close request function: TFAnalysisUIFigure
        function TFAnalysisUIFigureCloseRequest(app, event)
            app.Mainapp.EventLFPTF = [];

            if ~isempty(app.Mainapp.ProbeViewWindowHandle) % delete option from probe view 
                for i = 1:length(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                    if strcmp(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Event Time Frequency Power Window')
                        app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items(i) = [];
                        break;
                    end
                end
            else % if no probe view available
 
            end

            delete(app);
            
        end

        % Value changed function: DataToExtractFromDropDown
        function DataToExtractFromDropDownValueChanged(app, event)
            %% -------------------- Extract Event Related Data -------------------- 
            [app.Mainapp.Data,~] = Event_Module_Extract_Event_Related_Data(app.Mainapp.Data,app.EventTriggerChannel.Value,app.TFInfos.Timearoundevent,app.DataToExtractFromDropDown.Value,app.DataSourceDropDown.Value);
            
            %% -------------------- Populate event channel selection -------------------- 
            [app.EventNumberSelectionEditField.Value] = Event_Module_Check_EventInput(app.EventNumberSelectionEditField.Value,app.Mainapp.Data,app.EventTriggerChannel.Value,app.DataSourceDropDown.Value,1);
            
            %% -------------------- PLOT -------------------- 

            [TF] = Event_Module_Organize_TF_Window_Inputs(app.Mainapp.Data,app.DataSourceDropDown_2.Value,app.DataSourceDropDown_3.Value,app.Mainapp.ActiveChannel,app.EventNumberSelectionEditField.Value,app.FrequencyRangeminmaxstepsEditField.Value,app.CycleWidthfromto23EditField.Value,app.FrequencyRangeminmaxstepsEditField_2.Value,app.CoherencebetweenTrialsCheckBox.Value,app.CoherencebetweenChannelCheckBox.Value,app.SmoothingEditField_2.Value);
    
            if strcmp(app.DataToExtractFromDropDown.Value,'Raw Data')                
                if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                end
            else
                if isfield(app.Mainapp.Data.Info,"DownsampleFactor")
                    if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    end
                else
                    if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    end
                end
            end
            
        end

        % Value changed function: EventTriggerChannel
        function EventTriggerChannelValueChanged(app, event)
            %% -------------------- Check if prepro event related data found and can be offered -------------------- 
            [texttoshow,Value] = Event_Module_Check_Event_Preprocessing(app.Mainapp.Data,app.EventTriggerChannel.Value,app.DataSourceDropDown.Value);
            app.DataSourceDropDown.Items = {};
            app.DataSourceDropDown.Items = texttoshow;
            app.DataSourceDropDown.Value = Value;
            
            %% -------------------- Extract Event Related Data -------------------- 
            [app.Mainapp.Data,~] = Event_Module_Extract_Event_Related_Data(app.Mainapp.Data,app.EventTriggerChannel.Value,app.TFInfos.Timearoundevent,app.DataToExtractFromDropDown.Value,app.DataSourceDropDown.Value);
            
            %% -------------------- Set Basline Normlaization and trigger number -------------------- 
            if strcmp(app.DataSourceDropDown.Value,'Raw Event Related Data')
                app.EventNumberSelectionEditField_2.Value = strcat(num2str(size(app.Mainapp.Data.EventRelatedData,2)),' Trigger');
            else
                app.EventNumberSelectionEditField_2.Value = strcat(num2str(size(app.Mainapp.Data.PreprocessedEventRelatedData,2)),' Trigger');
            end

            %% -------------------- Populate event channel selection -------------------- 
            [app.EventNumberSelectionEditField.Value] = Event_Module_Check_EventInput(app.EventNumberSelectionEditField.Value,app.Mainapp.Data,app.EventTriggerChannel.Value,app.DataSourceDropDown.Value,1);
             
            %% -------------------- PLOT -------------------- 

            [TF] = Event_Module_Organize_TF_Window_Inputs(app.Mainapp.Data,app.DataSourceDropDown_2.Value,app.DataSourceDropDown_3.Value,app.Mainapp.ActiveChannel,app.EventNumberSelectionEditField.Value,app.FrequencyRangeminmaxstepsEditField.Value,app.CycleWidthfromto23EditField.Value,app.FrequencyRangeminmaxstepsEditField_2.Value,app.CoherencebetweenTrialsCheckBox.Value,app.CoherencebetweenChannelCheckBox.Value,app.SmoothingEditField_2.Value);
    
            if strcmp(app.DataToExtractFromDropDown.Value,'Raw Data')                
                if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                end
            else
                if isfield(app.Mainapp.Data.Info,"DownsampleFactor")
                    if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    end
                else
                    if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    end
                end
            end
            
        end

        % Menu selected function: SVGMenu
        function SVGMenuSelected(app, event)
            [file, path] = uiputfile('*.svg', 'Save as');
            
            exportgraphics(app.TFAnalysisUIFigure, fullfile(path,file), 'ContentType', 'vector');
        end

        % Menu selected function: xlsxMenu
        function xlsxMenuSelected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;
            
            if app.TimeFrequencyCheckBox.Value == 1
                AddonName = "Event Related Time Frequency Power";
            elseif app.IntertrialPhaseClusteringCheckBox.Value == 1
                AddonName = "Event Related Intertrial Phase Clustering";
            end

            if app.PhaseindependentCheckBox.Value == 1
                AddonName = strcat("Phase Independent ",AddonName);
            elseif app.PhaselockedCheckBox.Value == 1
                AddonName = strcat("Phase Locked ",AddonName);
            elseif app.NonphaselockedCheckBox.Value == 1
                AddonName = strcat("Non Phase Locked ",AddonName);
            end

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".xlsx",app.Mainapp.executableFolder,TimeRangePlot,StartTime,AddonName,0);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            Utility_Open_UIAxes_as_Figure(app.Mainapp.Data,app.UIAxes,"EventTF");
        end

        % Value changed function: BaselineNormalizeCheckBox
        function BaselineNormalizeCheckBoxValueChanged(app, event)
            %% -------------------- Plot -------------------- 

            [TF] = Event_Module_Organize_TF_Window_Inputs(app.Mainapp.Data,app.DataSourceDropDown_2.Value,app.DataSourceDropDown_3.Value,app.Mainapp.ActiveChannel,app.EventNumberSelectionEditField.Value,app.FrequencyRangeminmaxstepsEditField.Value,app.CycleWidthfromto23EditField.Value,app.FrequencyRangeminmaxstepsEditField_2.Value,app.CoherencebetweenTrialsCheckBox.Value,app.CoherencebetweenChannelCheckBox.Value,app.SmoothingEditField_2.Value);

            if strcmp(app.DataToExtractFromDropDown.Value,'Raw Data')           
                if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                end
            else
                if isfield(app.Mainapp.Data.Info,"DownsampleFactor")
                    if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    end
                else
                    if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    end
                end
            end

            cb = colorbar(app.UIAxes);
            cb.Color = 'k';              % Sets tick mark and label color to black
            cb.Label.String = app.Mainapp.PlotAppearance.TFWindow.CLabel;
            cb.Label.Rotation = 270;
            cb.FontSize =  app.Mainapp.PlotAppearance.TFWindow.FontSize;  
            cb.Label.Color = 'k';        % Sets the color of the label text
            
        end

        % Value changed function: EventNumberSelectionEditField_3
        function EventNumberSelectionEditField_3ValueChanged(app, event)
            %% -------------------- Plot -------------------- 

            [TF] = Event_Module_Organize_TF_Window_Inputs(app.Mainapp.Data,app.DataSourceDropDown_2.Value,app.DataSourceDropDown_3.Value,app.Mainapp.ActiveChannel,app.EventNumberSelectionEditField.Value,app.FrequencyRangeminmaxstepsEditField.Value,app.CycleWidthfromto23EditField.Value,app.FrequencyRangeminmaxstepsEditField_2.Value,app.CoherencebetweenTrialsCheckBox.Value,app.CoherencebetweenChannelCheckBox.Value,app.SmoothingEditField_2.Value);

            if strcmp(app.DataToExtractFromDropDown.Value,'Raw Data')           
                if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                end
            else
                if isfield(app.Mainapp.Data.Info,"DownsampleFactor")
                    if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    end
                else
                    if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    end
                end
            end

            cb = colorbar(app.UIAxes);
            cb.Color = 'k';              % Sets tick mark and label color to black
            cb.Label.String = app.Mainapp.PlotAppearance.TFWindow.CLabel;
            cb.Label.Rotation = 270;
            cb.FontSize =  app.Mainapp.PlotAppearance.TFWindow.FontSize;  
            cb.Label.Color = 'k';        % Sets the color of the label text
            
        end

        % Value changed function: DataSourceDropDown_2
        function DataSourceDropDown_2ValueChanged(app, event)
            value = app.DataSourceDropDown_2.Value;
            app.UIAxes.NextPlot = "replace";
            cla(app.UIAxes)
            app.UIAxes.NextPlot = "add";
            app.UIAxes.YScale = 'linear';
            app.UIAxes.YTickMode = 'auto';
            app.UIAxes.YTickLabelMode = 'auto';

            %% -------------------- Plot -------------------- 

            [TF] = Event_Module_Organize_TF_Window_Inputs(app.Mainapp.Data,app.DataSourceDropDown_2.Value,app.DataSourceDropDown_3.Value,app.Mainapp.ActiveChannel,app.EventNumberSelectionEditField.Value,app.FrequencyRangeminmaxstepsEditField.Value,app.CycleWidthfromto23EditField.Value,app.FrequencyRangeminmaxstepsEditField_2.Value,app.CoherencebetweenTrialsCheckBox.Value,app.CoherencebetweenChannelCheckBox.Value,app.SmoothingEditField_2.Value);

            if strcmp(app.DataToExtractFromDropDown.Value,'Raw Data')           
                if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                end
            else
                if isfield(app.Mainapp.Data.Info,"DownsampleFactor")
                    if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    end
                else
                    if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    end
                end
            end
        end

        % Value changed function: DataSourceDropDown_3
        function DataSourceDropDown_3ValueChanged(app, event)
            app.UIAxes.NextPlot = "replace";
            cla(app.UIAxes)
            app.UIAxes.NextPlot = "add";
            app.UIAxes.YScale = 'linear';
            app.UIAxes.YTickMode = 'auto';
            app.UIAxes.YTickLabelMode = 'auto';

            Freqs = str2double(strsplit(app.FrequencyRangeminmaxstepsEditField.Value,','));

            if Freqs(2)>32 && strcmp(app.DataSourceDropDown_3.Value,"Time Varying Wavelet Coherence")
                Freqs(2) = 32;
                app.FrequencyRangeminmaxstepsEditField.Value = strjoin(string(Freqs),',');
            end

            if strcmp(app.DataSourceDropDown_3.Value,"Single Channel ERP Spectogram")
                app.SmoothingEditField_2.Enable = "off";
                app.EventNumberSelectionEditField.Enable = "on";
                app.EventNumberSelectionEditField_3.Enable = "on";
                app.BaselineNormalizeCheckBox.Enable = "on";
                app.DataSourceDropDown_2.Enable = "on";
                app.CoherencebetweenChannelCheckBox.Value = 0;
                app.CoherencebetweenTrialsCheckBox.Value = 0;
                app.CoherencebetweenTrialsCheckBox.Enable = "off";
                app.CoherencebetweenChannelCheckBox.Enable = "off";
                app.FrequencyRangeminmaxstepsEditField_2.Enable = "off";

            elseif strcmp(app.DataSourceDropDown_3.Value,"Time Varying Wavelet Coherence")
                app.SmoothingEditField_2.Enable = "on";
                app.EventNumberSelectionEditField_3.Enable = "off";
                app.BaselineNormalizeCheckBox.Enable = "off";
                app.CoherencebetweenTrialsCheckBox.Enable = "on";
                app.CoherencebetweenChannelCheckBox.Enable = "on";
                app.FrequencyRangeminmaxstepsEditField_2.Enable = "on";
                if double(app.CoherencebetweenTrialsCheckBox.Value) + double(app.CoherencebetweenChannelCheckBox.Value) <1
                    app.CoherencebetweenTrialsCheckBox.Value = 1;
                    app.TriggerToComparecommaseparatedLabel.Text = "Trigger To Compare (comma separated)";
                end
                
                if app.CoherencebetweenTrialsCheckBox.Value == 1
                    app.DataSourceDropDown_2.Enable = "on";
                else
                    app.DataSourceDropDown_2.Enable = "off";
                end
                if app.CoherencebetweenTrialsCheckBox.Value == 1
                    app.EventNumberSelectionEditField.Enable = "off";
                else
                    app.EventNumberSelectionEditField.Enable = "on";
                end

            end

            %% -------------------- Plot -------------------- 

            [TF] = Event_Module_Organize_TF_Window_Inputs(app.Mainapp.Data,app.DataSourceDropDown_2.Value,app.DataSourceDropDown_3.Value,app.Mainapp.ActiveChannel,app.EventNumberSelectionEditField.Value,app.FrequencyRangeminmaxstepsEditField.Value,app.CycleWidthfromto23EditField.Value,app.FrequencyRangeminmaxstepsEditField_2.Value,app.CoherencebetweenTrialsCheckBox.Value,app.CoherencebetweenChannelCheckBox.Value,app.SmoothingEditField_2.Value);

            if strcmp(app.DataToExtractFromDropDown.Value,'Raw Data')           
                if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                end
            else
                if isfield(app.Mainapp.Data.Info,"DownsampleFactor")
                    if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    end
                else
                    if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    end
                end
            end
        end

        % Value changed function: CoherencebetweenTrialsCheckBox
        function CoherencebetweenTrialsCheckBoxValueChanged(app, event)
            app.UIAxes.NextPlot = "replace";
            cla(app.UIAxes)
            app.UIAxes.NextPlot = "add";
            app.UIAxes.YScale = 'linear';
            app.UIAxes.YTickMode = 'auto';
            app.UIAxes.YTickLabelMode = 'auto';
            if app.CoherencebetweenTrialsCheckBox.Value == 1
                app.EventNumberSelectionEditField.Enable = "off";
                app.DataSourceDropDown_2.Enable = "on";
                app.EventNumberSelectionEditField.Enable = "off";
                if app.CoherencebetweenChannelCheckBox.Value == 1
                    app.CoherencebetweenChannelCheckBox.Value = 0;
                end
                app.TriggerToComparecommaseparatedLabel.Text = "Trigger To Compare (comma separated)";
            else
                app.EventNumberSelectionEditField.Enable = "on";
                app.EventNumberSelectionEditField.Enable = "on";
                app.DataSourceDropDown_2.Enable = "off";
                if app.CoherencebetweenChannelCheckBox.Value == 0
                    app.CoherencebetweenChannelCheckBox.Value = 1;
                end
                app.TriggerToComparecommaseparatedLabel.Text = "Channel To Compare (comma separated)";
            end

            [TF] = Event_Module_Organize_TF_Window_Inputs(app.Mainapp.Data,app.DataSourceDropDown_2.Value,app.DataSourceDropDown_3.Value,app.Mainapp.ActiveChannel,app.EventNumberSelectionEditField.Value,app.FrequencyRangeminmaxstepsEditField.Value,app.CycleWidthfromto23EditField.Value,app.FrequencyRangeminmaxstepsEditField_2.Value,app.CoherencebetweenTrialsCheckBox.Value,app.CoherencebetweenChannelCheckBox.Value,app.SmoothingEditField_2.Value);

            if strcmp(app.DataToExtractFromDropDown.Value,'Raw Data')           
                if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                end
            else
                if isfield(app.Mainapp.Data.Info,"DownsampleFactor")
                    if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    end
                else
                    if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    end
                end
            end
        end

        % Value changed function: CoherencebetweenChannelCheckBox
        function CoherencebetweenChannelCheckBoxValueChanged(app, event)
            app.UIAxes.NextPlot = "replace";
            cla(app.UIAxes)
            app.UIAxes.NextPlot = "add";
            app.UIAxes.YScale = 'linear';
            app.UIAxes.YTickMode = 'auto';
            app.UIAxes.YTickLabelMode = 'auto';
            if app.CoherencebetweenChannelCheckBox.Value == 1
                app.EventNumberSelectionEditField.Enable = "on";
                app.DataSourceDropDown_2.Enable = "off";
                if app.CoherencebetweenTrialsCheckBox.Value == 1
                    app.CoherencebetweenTrialsCheckBox.Value = 0;
                end
                app.TriggerToComparecommaseparatedLabel.Text = "Channel To Compare (comma separated)";
            else
                app.EventNumberSelectionEditField.Enable = "off";
                app.DataSourceDropDown_2.Enable = "on";
                if app.CoherencebetweenTrialsCheckBox.Value == 0
                    app.CoherencebetweenTrialsCheckBox.Value = 1;
                end
                app.TriggerToComparecommaseparatedLabel.Text = "Trigger To Compare (comma separated)";
            end

            [TF] = Event_Module_Organize_TF_Window_Inputs(app.Mainapp.Data,app.DataSourceDropDown_2.Value,app.DataSourceDropDown_3.Value,app.Mainapp.ActiveChannel,app.EventNumberSelectionEditField.Value,app.FrequencyRangeminmaxstepsEditField.Value,app.CycleWidthfromto23EditField.Value,app.FrequencyRangeminmaxstepsEditField_2.Value,app.CoherencebetweenTrialsCheckBox.Value,app.CoherencebetweenChannelCheckBox.Value,app.SmoothingEditField_2.Value);

            if strcmp(app.DataToExtractFromDropDown.Value,'Raw Data')           
                if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                end
            else
                if isfield(app.Mainapp.Data.Info,"DownsampleFactor")
                    if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    end
                else
                    if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    end
                end
            end
        end

        % Value changed function: FrequencyRangeminmaxstepsEditField_2
        function FrequencyRangeminmaxstepsEditField_2ValueChanged(app, event)
            app.UIAxes.NextPlot = "replace";
            cla(app.UIAxes)
            app.UIAxes.NextPlot = "add";
            app.UIAxes.YScale = 'linear';
            app.UIAxes.YTickMode = 'auto';
            app.UIAxes.YTickLabelMode = 'auto';
            value = app.FrequencyRangeminmaxstepsEditField_2.Value;
            [TF] = Event_Module_Organize_TF_Window_Inputs(app.Mainapp.Data,app.DataSourceDropDown_2.Value,app.DataSourceDropDown_3.Value,app.Mainapp.ActiveChannel,app.EventNumberSelectionEditField.Value,app.FrequencyRangeminmaxstepsEditField.Value,app.CycleWidthfromto23EditField.Value,app.FrequencyRangeminmaxstepsEditField_2.Value,app.CoherencebetweenTrialsCheckBox.Value,app.CoherencebetweenChannelCheckBox.Value,app.SmoothingEditField_2.Value);

            if strcmp(app.DataToExtractFromDropDown.Value,'Raw Data')           
                if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                end
            else
                if isfield(app.Mainapp.Data.Info,"DownsampleFactor")
                    if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    end
                else
                    if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    end
                end
            end
        end

        % Value changed function: SmoothingEditField_2
        function SmoothingEditField_2ValueChanged(app, event)
            app.UIAxes.NextPlot = "replace";
            cla(app.UIAxes)
            app.UIAxes.NextPlot = "add";
            app.UIAxes.YScale = 'linear';
            app.UIAxes.YTickMode = 'auto';
            app.UIAxes.YTickLabelMode = 'auto';
    
            [TF] = Event_Module_Organize_TF_Window_Inputs(app.Mainapp.Data,app.DataSourceDropDown_2.Value,app.DataSourceDropDown_3.Value,app.Mainapp.ActiveChannel,app.EventNumberSelectionEditField.Value,app.FrequencyRangeminmaxstepsEditField.Value,app.CycleWidthfromto23EditField.Value,app.FrequencyRangeminmaxstepsEditField_2.Value,app.CoherencebetweenTrialsCheckBox.Value,app.CoherencebetweenChannelCheckBox.Value,app.SmoothingEditField_2.Value);

            if strcmp(app.DataToExtractFromDropDown.Value,'Raw Data')           
                if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                    [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                end
            else
                if isfield(app.Mainapp.Data.Info,"DownsampleFactor")
                    if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.DownsampledSampleRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    end
                else
                    if strcmp(app.DataSourceDropDown.Value,"Raw Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    elseif strcmp(app.DataSourceDropDown.Value,"Preprocessed Event Related Data")
                        [app.climsTF,app.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.Mainapp.Data.Info.NativeSamplingRate,app.TFInfos.Timearoundevent,TF,app.CycleWidthfromto23EditField.Value,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
                    end
                end
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TFAnalysisUIFigure = uifigure('Visible', 'off');
            app.TFAnalysisUIFigure.Name = 'Time Frequency Power Window';
            app.TFAnalysisUIFigure.Icon = 'Logo.png';
            app.TFAnalysisUIFigure.CloseRequestFcn = createCallbackFcn(app, @TFAnalysisUIFigureCloseRequest, true);
            app.SnapshotMenu = uimenu(app.TFAnalysisUIFigure);
            app.SnapshotMenu.Text = '   Snapshot   ';
            app.SaveSnapshotofWindowMenu = uimenu(app.SnapshotMenu);
            app.SaveSnapshotofWindowMenu.Text = 'Save Snapshot of Window';
            app.PNGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.PNGMenu.MenuSelectedFcn = createCallbackFcn(app, @PNGMenuSelected, true);
            app.PNGMenu.Text = 'PNG';
            app.JPGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.JPGMenu.MenuSelectedFcn = createCallbackFcn(app, @JPGMenuSelected, true);
            app.JPGMenu.Text = 'JPG';
            app.SVGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.SVGMenu.MenuSelectedFcn = createCallbackFcn(app, @SVGMenuSelected, true);
            app.SVGMenu.Text = 'SVG';
            app.PlotMenu = uimenu(app.TFAnalysisUIFigure);
            app.PlotMenu.Text = '   Plot   ';
            app.Show2DimensionalPlotsMenu = uimenu(app.PlotMenu);
            app.Show2DimensionalPlotsMenu.MenuSelectedFcn = createCallbackFcn(app, @Show2DimensionalPlotsMenuSelected, true);
            app.Show2DimensionalPlotsMenu.Text = 'Show 2 Dimensional Plots';
            app.Show3DimensionalPlotsMenu = uimenu(app.PlotMenu);
            app.Show3DimensionalPlotsMenu.MenuSelectedFcn = createCallbackFcn(app, @Show3DimensionalPlotsMenuSelected, true);
            app.Show3DimensionalPlotsMenu.Text = 'Show 3 Dimensional Plots';
            app.TFPlotMenu = uimenu(app.PlotMenu);
            app.TFPlotMenu.Text = 'TF Plot';
            app.FontSizeMenu = uimenu(app.TFPlotMenu);
            app.FontSizeMenu.MenuSelectedFcn = createCallbackFcn(app, @FontSizeMenuSelected, true);
            app.FontSizeMenu.Text = 'FontSize';
            app.LineWidthTriggerMenu = uimenu(app.TFPlotMenu);
            app.LineWidthTriggerMenu.MenuSelectedFcn = createCallbackFcn(app, @LineWidthTriggerMenuSelected, true);
            app.LineWidthTriggerMenu.Text = 'LineWidth Trigger';
            app.ColorTriggerMenu = uimenu(app.TFPlotMenu);
            app.ColorTriggerMenu.MenuSelectedFcn = createCallbackFcn(app, @ColorTriggerMenuSelected, true);
            app.ColorTriggerMenu.Text = 'Color Trigger';
            app.BackgorundColorMenu = uimenu(app.TFPlotMenu);
            app.BackgorundColorMenu.MenuSelectedFcn = createCallbackFcn(app, @BackgorundColorMenuSelected, true);
            app.BackgorundColorMenu.Text = 'Backgorund Color';
            app.XLabelMenu = uimenu(app.TFPlotMenu);
            app.XLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @XLabelMenuSelected, true);
            app.XLabelMenu.Text = 'XLabel';
            app.YLabelMenu = uimenu(app.TFPlotMenu);
            app.YLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @YLabelMenuSelected, true);
            app.YLabelMenu.Text = 'YLabel';
            app.CLabelMenu = uimenu(app.TFPlotMenu);
            app.CLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @CLabelMenuSelected, true);
            app.CLabelMenu.Text = 'CLabel';
            app.RESETMenu = uimenu(app.TFPlotMenu);
            app.RESETMenu.MenuSelectedFcn = createCallbackFcn(app, @RESETMenuSelected, true);
            app.RESETMenu.Text = 'RESET';
            app.SaveCostumeAppearanceMenu = uimenu(app.PlotMenu);
            app.SaveCostumeAppearanceMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveCostumeAppearanceMenuSelected, true);
            app.SaveCostumeAppearanceMenu.Text = 'Save Costume Appearance';
            app.DeleteCostumeAppearanceMenu = uimenu(app.PlotMenu);
            app.DeleteCostumeAppearanceMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteCostumeAppearanceMenuSelected, true);
            app.DeleteCostumeAppearanceMenu.Text = 'Delete Costume Appearance';
            app.ExportDataMenu = uimenu(app.TFAnalysisUIFigure);
            app.ExportDataMenu.Text = '   Export Data   ';
            app.matMenu = uimenu(app.ExportDataMenu);
            app.matMenu.MenuSelectedFcn = createCallbackFcn(app, @matMenuSelected, true);
            app.matMenu.Text = '.mat';
            app.txtMenu = uimenu(app.ExportDataMenu);
            app.txtMenu.MenuSelectedFcn = createCallbackFcn(app, @txtMenuSelected, true);
            app.txtMenu.Text = '.txt';
            app.xlsxMenu = uimenu(app.ExportDataMenu);
            app.xlsxMenu.MenuSelectedFcn = createCallbackFcn(app, @xlsxMenuSelected, true);
            app.xlsxMenu.Text = '.xlsx';
            app.LeftPanel = uipanel(app.TFAnalysisUIFigure);
            app.RightPanel = uipanel(app.TFAnalysisUIFigure);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.EventandChannelSelectionPanel = uipanel(app.RightPanel);
            app.EventandChannelSelectionPanel.Title = 'Event and Channel Selection';
            app.TriggerNumberSelectionMatlabExpressionsLabel = uilabel(app.EventandChannelSelectionPanel);
            app.TriggerNumberSelectionMatlabExpressionsLabel.Text = {'Trigger Number  Selection '; '(Matlab Expressions)'};
            app.EventNumberSelectionEditField = uieditfield(app.EventandChannelSelectionPanel, 'text');
            app.EventNumberSelectionEditField.ValueChangedFcn = createCallbackFcn(app, @EventNumberSelectionEditFieldValueChanged, true);
            app.EventDataTypeDropDownLabel = uilabel(app.EventandChannelSelectionPanel);
            app.EventDataTypeDropDownLabel.Text = 'Event Data Type';
            app.DataSourceDropDown = uidropdown(app.EventandChannelSelectionPanel);
            app.DataSourceDropDown.Items = {''};
            app.DataSourceDropDown.ValueChangedFcn = createCallbackFcn(app, @DataSourceDropDownValueChanged, true);
            app.DataSourceDropDown.Value = '';
            app.EventChannelSelectionDropDownLabel = uilabel(app.EventandChannelSelectionPanel);
            app.EventChannelSelectionDropDownLabel.Text = 'Event Channel Selection';
            app.EventTriggerChannel = uidropdown(app.EventandChannelSelectionPanel);
            app.EventTriggerChannel.Items = {};
            app.EventTriggerChannel.ValueChangedFcn = createCallbackFcn(app, @EventTriggerChannelValueChanged, true);
            app.EventTriggerChannel.Value = {};
            app.DataToExtractFromDropDownLabel = uilabel(app.EventandChannelSelectionPanel);
            app.DataToExtractFromDropDownLabel.Text = 'Data To Extract From';
            app.DataToExtractFromDropDown = uidropdown(app.EventandChannelSelectionPanel);
            app.DataToExtractFromDropDown.Items = {};
            app.DataToExtractFromDropDown.ValueChangedFcn = createCallbackFcn(app, @DataToExtractFromDropDownValueChanged, true);
            app.DataToExtractFromDropDown.Value = {};
            app.EventandChannelSelectionLabel = uilabel(app.EventandChannelSelectionPanel);
            app.EventandChannelSelectionLabel.Text = 'Event and Channel Selection';
            app.EventNumberSelectionEditField_2 = uieditfield(app.EventandChannelSelectionPanel, 'text');
            app.TriggerNumberSelectionMatlabExpressionsLabel_2 = uilabel(app.EventandChannelSelectionPanel);
            app.TriggerNumberSelectionMatlabExpressionsLabel_2.Text = 'Total Nr Of Trigger';
            app.BaselineNormalizeCheckBox = uicheckbox(app.EventandChannelSelectionPanel);
            app.BaselineNormalizeCheckBox.ValueChangedFcn = createCallbackFcn(app, @BaselineNormalizeCheckBoxValueChanged, true);
            app.BaselineNormalizeCheckBox.Text = {'   Baseline '; '   Normalize'};
            app.TriggerNumberSelectionMatlabExpressionsLabel_3 = uilabel(app.EventandChannelSelectionPanel);
            app.TriggerNumberSelectionMatlabExpressionsLabel_3.Text = {'Baseline Window'; '[from, to] in s'};
            app.EventNumberSelectionEditField_3 = uieditfield(app.EventandChannelSelectionPanel, 'text');
            app.EventNumberSelectionEditField_3.ValueChangedFcn = createCallbackFcn(app, @EventNumberSelectionEditField_3ValueChanged, true);
            app.CloseButton = uibutton(app.RightPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.AnalysisParameterPanel = uipanel(app.RightPanel);
            app.AnalysisParameterPanel.Title = 'Analysis Parameter';
            app.WindowSizeSamplesLabel = uilabel(app.AnalysisParameterPanel);
            app.WindowSizeSamplesLabel.Text = 'Window Size [Samples]';
            app.CycleWidthfromto23EditField = uieditfield(app.AnalysisParameterPanel, 'text');
            app.CycleWidthfromto23EditField.ValueChangedFcn = createCallbackFcn(app, @CycleWidthfromto23EditFieldValueChanged, true);
            app.CycleWidthfromto23EditField.Value = '500';
            app.FrequRangeminstepsmaxLabel = uilabel(app.AnalysisParameterPanel);
            app.FrequRangeminstepsmaxLabel.Text = {'Frequ. Range'; '(min,steps,max)'};
            app.FrequencyRangeminmaxstepsEditField = uieditfield(app.AnalysisParameterPanel, 'text');
            app.FrequencyRangeminmaxstepsEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyRangeminmaxstepsEditFieldValueChanged, true);
            app.FrequencyRangeminmaxstepsEditField.Value = '1,32,30';
            app.AutoClimButton = uibutton(app.AnalysisParameterPanel, 'push');
            app.AutoClimButton.ButtonPushedFcn = createCallbackFcn(app, @AutoClimButtonPushed, true);
            app.AutoClimButton.Text = 'Auto Clim';
            app.ClimminmaxLabel = uilabel(app.AnalysisParameterPanel);
            app.ClimminmaxLabel.Text = {'Clim '; '[min max]'};
            app.ClimminmaxEditField = uieditfield(app.AnalysisParameterPanel, 'text');
            app.ClimminmaxEditField.ValueChangedFcn = createCallbackFcn(app, @ClimminmaxEditFieldValueChanged, true);
            app.AnalysisParameterLabel = uilabel(app.AnalysisParameterPanel);
            app.AnalysisParameterLabel.Text = 'Analysis Parameter';
            app.ChannelToAnalyzeLabel = uilabel(app.AnalysisParameterPanel);
            app.ChannelToAnalyzeLabel.Text = 'Channel To Analyze';
            app.DataSourceDropDown_2 = uidropdown(app.AnalysisParameterPanel);
            app.DataSourceDropDown_2.Items = {''};
            app.DataSourceDropDown_2.ValueChangedFcn = createCallbackFcn(app, @DataSourceDropDown_2ValueChanged, true);
            app.DataSourceDropDown_2.Value = '';
            app.ChannelToAnalyzeLabel_2 = uilabel(app.AnalysisParameterPanel);
            app.ChannelToAnalyzeLabel_2.Text = 'Analysis Type';
            app.DataSourceDropDown_3 = uidropdown(app.AnalysisParameterPanel);
            app.DataSourceDropDown_3.Items = {'Single Channel ERP Spectogram', 'Time Varying Wavelet Coherence'};
            app.DataSourceDropDown_3.ValueChangedFcn = createCallbackFcn(app, @DataSourceDropDown_3ValueChanged, true);
            app.DataSourceDropDown_3.Value = 'Single Channel ERP Spectogram';
            app.CoherencebetweenTrialsCheckBox = uicheckbox(app.AnalysisParameterPanel);
            app.CoherencebetweenTrialsCheckBox.ValueChangedFcn = createCallbackFcn(app, @CoherencebetweenTrialsCheckBoxValueChanged, true);
            app.CoherencebetweenTrialsCheckBox.Text = '   Coherence between Trigger';
            app.CoherencebetweenChannelCheckBox = uicheckbox(app.AnalysisParameterPanel);
            app.CoherencebetweenChannelCheckBox.ValueChangedFcn = createCallbackFcn(app, @CoherencebetweenChannelCheckBoxValueChanged, true);
            app.CoherencebetweenChannelCheckBox.Text = '   Coherence between Channel';
            app.FrequencyRangeminmaxstepsEditField_2 = uieditfield(app.AnalysisParameterPanel, 'text');
            app.FrequencyRangeminmaxstepsEditField_2.ValueChangedFcn = createCallbackFcn(app, @FrequencyRangeminmaxstepsEditField_2ValueChanged, true);
            app.FrequencyRangeminmaxstepsEditField_2.Value = '1,2';
            app.TriggerToComparecommaseparatedLabel = uilabel(app.AnalysisParameterPanel);
            app.TriggerToComparecommaseparatedLabel.Text = {'Trigger To Compare'; '(comma separated)'};
            app.WindowSizeSamplesLabel_2 = uilabel(app.AnalysisParameterPanel);
            app.WindowSizeSamplesLabel_2.Text = 'Smoothing (Nr Scales)';
            app.SmoothingEditField_2 = uieditfield(app.AnalysisParameterPanel, 'text');
            app.SmoothingEditField_2.ValueChangedFcn = createCallbackFcn(app, @SmoothingEditField_2ValueChanged, true);
            app.SmoothingEditField_2.Value = '9';
            app.ChannelLabel = uilabel(app.RightPanel);
            app.ChannelLabel.Enable = 'off';
            app.ChannelLabel.Visible = 'off';
            app.ChannelLabel.Text = {'Channel Selection'; '[from,to]'};
            app.ChannelSelectionDropDown = uieditfield(app.RightPanel, 'text');
            app.ChannelSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelSelectionDropDownValueChanged, true);
            app.ChannelSelectionDropDown.Editable = 'off';
            app.ChannelSelectionDropDown.Enable = 'off';
            app.ChannelSelectionDropDown.Visible = 'off';
            app.Button = uibutton(app.RightPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'Open_Figure_Icon.png';
            app.Button.Text = '';
            % Show the figure after all components are created
            app.TFAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Time_Frequency_Power_Window_Spectrogram(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.TFAnalysisUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.TFAnalysisUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TFAnalysisUIFigure)
        end
    end
end