classdef Preprocessing_Events_Trial_Rejection_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TrialRejectionWindowUIFigure    matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        TrialRejectionSettingsPanel_2   matlab.ui.container.Panel
        AddTrialsToRejectionButton      matlab.ui.control.Button
        CheckBox_2                      matlab.ui.control.CheckBox
        CheckBox                        matlab.ui.control.CheckBox
        TrialsCrossingThresholdLabel    matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        PlotThresholdCheckBox           matlab.ui.control.CheckBox
        AutomaticOutlierDetectionLabel  matlab.ui.control.Label
        ThresholdEditField              matlab.ui.control.EditField
        ThresholdEditFieldLabel         matlab.ui.control.Label
        CloseButton                     matlab.ui.control.Button
        SaveasnewDatasetButton          matlab.ui.control.Button
        TrialRejectionSettingsPanel     matlab.ui.container.Panel
        ClimfromtoEditField             matlab.ui.control.EditField
        ClimfromtoEditFieldLabel        matlab.ui.control.Label
        DataToExtractFromDropDown       matlab.ui.control.DropDown
        DataToExtractFromDropDownLabel  matlab.ui.control.Label
        EventChannelSelectionDropDown   matlab.ui.control.DropDown
        EventChannelSelectionDropDownLabel  matlab.ui.control.Label
        PlotTrialsfromtoEditField       matlab.ui.control.EditField
        PlotTrialsfromtoLabel           matlab.ui.control.Label
        TrialRejectionSettingsLabel     matlab.ui.control.Label
        RejectTrialsfromtoEditField     matlab.ui.control.EditField
        RejectTrialsfromtoLabel         matlab.ui.control.Label
        ChannelofInterestDropDown       matlab.ui.control.DropDown
        ChannelofInteresjustforplottLabel  matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    %% All of the following code in this section is to display infos when clicking on plots

    properties (Access = public)
        % Add a property to store the names of the vectors
        TextHandle = []; % Store the handle to the displayed text
        TrialsTextHandle = []; % Store the handle to the displayed text
        Mainapp = [];
        EventTime = [];
        EventsRelatedApp = [];
        TempEventRelatedData = [];
        TimearoundEvent = [];
        ThresholdSign = [];
        LineHandles = [];
        LineHandles2 = [];
        AllYLabels = [];

        StartedFrom = [];
    end
        
    
    methods (Access = private)
      
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp, EventsRelatedApp, StartedFrom)
            movegui(app.TrialRejectionWindowUIFigure, 'center');

            app.Mainapp = Mainapp;
            app.EventsRelatedApp = EventsRelatedApp;
            app.StartedFrom = StartedFrom;

            if strcmp(app.StartedFrom,"EventPreprocessing")
                app.SaveasnewDatasetButton.Text = "Save as Preprocessed Event Data";
            else
                app.SaveasnewDatasetButton.Text = "Save as New Event Trigger Data";
            end

            app = Utility_Change_Light_Dark_Mode(app,"Event_Trial_Rejection");

            %% -------------------- Set UP Window General things-------------------- 
            if isfield(app.Mainapp.Data,'Preprocessed')
                app.DataToExtractFromDropDown.Items{1} = 'Raw Data';
                app.DataToExtractFromDropDown.Items{2} = 'Preprocessed Data';
            else
                app.DataToExtractFromDropDown.Items{1} = 'Raw Data';
            end
            
            for i = 1:length(app.Mainapp.Data.Info.EventChannelNames)
                app.EventChannelSelectionDropDown.Items{i} = convertStringsToChars(app.Mainapp.Data.Info.EventChannelNames{i});
            end

            spaceindicie = strfind(app.Mainapp.Data.Info.EventRelatedDataTimeRange," ");
            app.TimearoundEvent(1) = str2double(app.Mainapp.Data.Info.EventRelatedDataTimeRange(1:spaceindicie(1)-1));
            app.TimearoundEvent(2) = str2double(app.Mainapp.Data.Info.EventRelatedDataTimeRange(spaceindicie(1)+1:end));
            
            app.ThresholdSign = [1,1];

            %% -------------------- Set UP Channel Selection -------------------- 

            AllChannels = {};
            if isfield(app.Mainapp.Data,'Raw')
                N = size(app.Mainapp.Data.Raw,1);  
            elseif isfield(app.Mainapp.Data,'Preprocessed')
                N = size(app.Mainapp.Data.Preprocessed,1);  
            end
            
            if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 1
                TempActiveChannel = (str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)*str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)+1)-sort(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
                for i = 1:N
                    AllChannels{i} = num2str(TempActiveChannel(i));
                end
            else
                for i = 1:N
                    AllChannels{i} = num2str(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel(i));
                end
            end

            app.ChannelofInterestDropDown.Items = AllChannels;
            
            %% -------------------- Extract Event Related Data -------------------- 
            if isfield(app.Mainapp.Data.Info,'EventRelatedPreprocessing')
                [app.Mainapp.Data,~] = Event_Module_Extract_Event_Related_Data(app.Mainapp.Data,app.EventChannelSelectionDropDown.Value,app.TimearoundEvent,app.DataToExtractFromDropDown.Value,"Preprocessed Event Related Data");
            else
                [app.Mainapp.Data,~] = Event_Module_Extract_Event_Related_Data(app.Mainapp.Data,app.EventChannelSelectionDropDown.Value,app.TimearoundEvent,app.DataToExtractFromDropDown.Value,"Raw Event Related Data");
            end

            %% -------------------- Populate event channel selection -------------------- 
            if isfield(app.Mainapp.Data.Info,'EventRelatedPreprocessing')
                [app.PlotTrialsfromtoEditField.Value] = Event_Module_Check_EventInput(app.PlotTrialsfromtoEditField.Value,app.Mainapp.Data,app.EventChannelSelectionDropDown.Value,"Preprocessed Event Related Data",1);
            else
                [app.PlotTrialsfromtoEditField.Value] = Event_Module_Check_EventInput(app.PlotTrialsfromtoEditField.Value,app.Mainapp.Data,app.EventChannelSelectionDropDown.Value,"Raw Event Related Data",1);
            end

            %% -------------------- PLOT -------------------- 
            if isfield(app.Mainapp.Data.Info,'EventRelatedPreprocessing')
                [~,~,~,ThreshTrials,app.AllYLabels] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.Mainapp.Data.Info.EventRelatedTime,'Plot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value,app.EventChannelSelectionDropDown.Value,app.ThresholdSign);
            else
                [~,~,~,ThreshTrials,app.AllYLabels] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.Mainapp.Data.Info.EventRelatedTime,'Plot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value,app.EventChannelSelectionDropDown.Value,app.ThresholdSign);
            end
            
            if ~isempty(ThreshTrials)
             % Convert to comma-separated string
                texttoshow = strjoin(string(ThreshTrials), ',');
            else
                texttoshow = '';
            end
            
            app.TextArea.Value = texttoshow;

            %app = Utility_Change_Light_Dark_Mode(app,"Event_Trial_Rejection");
            
            app.LineHandles2 = findall(app.UIAxes_2,'Type','Line','Tag','Trials');
            app.LineHandles = findall(app.UIAxes,'Tag','TrialsImageSC');
            
            for k = 1:numel(app.LineHandles2)
                app.LineHandles2(k).PickableParts = 'none';  % clicks pass through to axes
            end
            app.UIAxes_2.ButtonDownFcn = @(src,event) Utility_Trial_Rejection_ButtonDownFunction(app,src,event);
            
            app.Mainapp.EventIndiceRejectionWindow = app;

            [app.Mainapp] = Utility_Set_ToolTips(app.Mainapp,app.Mainapp.ShowToolTipsSetting,"DeleteEventIndices");


        end

        % Button pushed function: SaveasnewDatasetButton
        function SaveasnewDatasetButtonPushed(app, event)

            if isempty(app.RejectTrialsfromtoEditField.Value)
                msgbox("Error: No trigger selected to reject.")
                return;
            else
                Channel = [1,size(app.Mainapp.Data.Raw,1)];
                try
                    RejectTrials = eval(app.RejectTrialsfromtoEditField.Value);
                catch
                    msgbox("Error: Could not determine trigger to reject.")
                    return;
                end
            end

            if strcmp(app.StartedFrom,"EventPreprocessing")
                [app.Mainapp.Data,Error] = Preprocessing_Events_Add_Preprocessing_Info(app.Mainapp.Data,'Trial Rejection',Channel,RejectTrials,app.EventChannelSelectionDropDown.Value);
            else
                try
                    EventsToDelete = eval(app.RejectTrialsfromtoEditField.Value);
                    EventIndicie = [];

                    for i = 1:length(app.Mainapp.Data.Info.EventChannelNames)
                        if strcmp(app.Mainapp.Data.Info.EventChannelNames{i},app.EventChannelSelectionDropDown.Value)
                            EventIndicie = i;
                        end
                    end

                    AllEvents = 1:length(app.Mainapp.Data.Events{EventIndicie});
                        
                    if sum(ismember(EventsToDelete,AllEvents)) ~= length(EventsToDelete)
                        msgbox("Error: Some of the specified trials/triggers exceed the range of all trials/trigger available.")
                        return;
                    end

                    if length(AllEvents)==length(EventsToDelete)
                        if length(app.Mainapp.Data.Events)==1
                            if isfield(app.Mainapp.Data,"Events")
                                [app.Mainapp.Data,Error] = Organize_Delete_Dataset_Components(app.Mainapp.Data,"Events");
                            end
                        else
                            app.Mainapp.Data.Events(EventIndicie) = []; % delete cell entry
                            app.Mainapp.Data.Info.EventChannelNames(EventIndicie)=[];
                            app.Mainapp.CurrentEventChannel = 1;
                        end 
                    else
                        % Delete actual indices
                        TempEvents = app.Mainapp.Data.Events{EventIndicie};
                        TempEvents(EventsToDelete) = [];
                        app.Mainapp.Data.Events{EventIndicie} = TempEvents;
                    end
                    if isfield(app.Mainapp.Data,"EventRelatedData")
                        [app.Mainapp.Data,Error] = Organize_Delete_Dataset_Components(app.Mainapp.Data,"EventRelatedData");
                    end
                    if isfield(app.Mainapp.Data,"EventRelatedSpikes")
                        [app.Mainapp.Data,Error] = Organize_Delete_Dataset_Components(app.Mainapp.Data,"EventRelatedSpikes");
                    end
                    if isfield(app.Mainapp.Data,"PreprocessedEventRelatedData")
                        [app.Mainapp.Data,Error] = Organize_Delete_Dataset_Components(app.Mainapp.Data,"PreprocessedEventRelatedData");
                    end

                catch
                    msgbox("Format error for rejected trials edit field. Please use Matlab expressions only.");
                    return;
                end
            end

            if Error == 1
                return
            end

            Utility_Show_Info_Loaded_Data(app.Mainapp);
            
            if strcmp(app.StartedFrom,"EventPreprocessing")
                Preprocessing_Events_PopulateInfoText(app.EventsRelatedApp.TextArea,app.Mainapp.Data);
            else
                if ~isempty(app.Mainapp.EventChannelDropDown.Items)
                    app.Mainapp.EventChannelDropDown.Value = app.Mainapp.EventChannelDropDown.Items{1};
                end
                
                KeepSpikes = 0;
                KeepEvents = 0;
                if isfield(app.Mainapp.Data,'Spikes') && strcmp(app.Mainapp.Plotspikes,"Spikes")
                    KeepSpikes = 1;
                end
                if isfield(app.Mainapp.Data,'Events') && strcmp(app.Mainapp.PlotEvents,"Events")
                    KeepEvents = 1;
                end

                EventText_handles = findobj(app.Mainapp.UIAxes, 'Tag','EventLabel');
                if ~isempty(EventText_handles)
                    delete(EventText_handles(:));
                end
                
                [app.Mainapp] = Organize_Set_MainWindow_Dropdown(app.Mainapp,app.Mainapp.Data);

                Organize_Reset_Main_Plot(app.Mainapp,1,1,1,0,app.Mainapp.CurrentTimePoints);
                
                app.Mainapp = Utility_Change_Light_Dark_Mode(app.Mainapp,"MainWindow");
            
                %Organize_Delete_All_Open_Windows(app.Mainapp,0);
                pause(0.1);
                
            end

            msgbox("Rejection of selected trigger succesfull!");
         
            delete(app);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            try
                app.Mainapp.EventIndiceRejectionWindow = [];
            catch

            end

            delete(app);
        end

        % Value changed function: ChannelofInterestDropDown
        function ChannelofInterestDropDownValueChanged(app, event)
        
             if ~isempty(app.RejectTrialsfromtoEditField.Value)

                if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                    [~,~,~,ThreshTrials,app.AllYLabels] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.Mainapp.Data.Info.EventRelatedTime,'RejectandPlot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value,app.EventChannelSelectionDropDown.Value,app.ThresholdSign);
                else
                    [~,~,~,ThreshTrials,app.AllYLabels] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.Mainapp.Data.Info.EventRelatedTime,'RejectandPlot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value,app.EventChannelSelectionDropDown.Value,app.ThresholdSign);
                end

            else
                %% Get Data based on Input
                if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                    [~,~,~,ThreshTrials,app.AllYLabels] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.Mainapp.Data.Info.EventRelatedTime,'Plot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value,app.EventChannelSelectionDropDown.Value,app.ThresholdSign);
                else
                    [~,~,~,ThreshTrials,app.AllYLabels] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.Mainapp.Data.Info.EventRelatedTime,'Plot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value,app.EventChannelSelectionDropDown.Value,app.ThresholdSign);
                end
            end
             
            if ~isempty(ThreshTrials)
             % Convert to comma-separated string
                texttoshow = strjoin(string(ThreshTrials), ',');
            else
                texttoshow = '';
            end
            
            app.TextArea.Value = texttoshow;
            
            app.LineHandles2 = findall(app.UIAxes_2,'Type','Line','Tag','Trials');
            app.LineHandles = findall(app.UIAxes,'Tag','TrialsImageSC');

            for k = 1:numel(app.LineHandles2)
                app.LineHandles2(k).PickableParts = 'none';  % clicks pass through to axes
            end
            app.UIAxes_2.ButtonDownFcn = @(src,event) Utility_Trial_Rejection_ButtonDownFunction(app,src,event);
           
        end

        % Value changed function: ClimfromtoEditField
        function ClimfromtoEditFieldValueChanged(app, event)
            value = app.ClimfromtoEditField.Value;
            
            CommaTest = find(value == ',');
            Inputvalue(1,1) = str2double(value(1:CommaTest(1)-1));
            Inputvalue(1,2) = str2double(value(CommaTest+1:end));

            app.UIAxes.CLim = [Inputvalue(1),Inputvalue(2)];
        end

        % Value changed function: RejectTrialsfromtoEditField
        function RejectTrialsfromtoEditFieldValueChanged(app, event)
            
            if contains(app.RejectTrialsfromtoEditField.Value,',')
                if ~contains(app.RejectTrialsfromtoEditField.Value,'[')
                    app.RejectTrialsfromtoEditField.Value = strcat('[',app.RejectTrialsfromtoEditField.Value);
                end
                if  ~contains(app.RejectTrialsfromtoEditField.Value,']')
                    app.RejectTrialsfromtoEditField.Value = strcat(app.RejectTrialsfromtoEditField.Value,']');
                end
            end
            
            if ~isempty(app.RejectTrialsfromtoEditField.Value)
            %% Get Data based on Input

                if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                    [app.TempEventRelatedData,~,~,ThreshTrials,app.AllYLabels] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.Mainapp.Data.Info.EventRelatedTime,'RejectandPlot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value,app.EventChannelSelectionDropDown.Value,app.ThresholdSign);
                else
                    [app.TempEventRelatedData,~,~,ThreshTrials,app.AllYLabels] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.Mainapp.Data.Info.EventRelatedTime,'RejectandPlot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value,app.EventChannelSelectionDropDown.Value,app.ThresholdSign);
                end

            else
                if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                    [app.TempEventRelatedData,~,~,ThreshTrials,app.AllYLabels] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.Mainapp.Data.Info.EventRelatedTime,'Plot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value,app.EventChannelSelectionDropDown.Value,app.ThresholdSign);
                else
                    [app.TempEventRelatedData,~,~,ThreshTrials,app.AllYLabels] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.Mainapp.Data.Info.EventRelatedTime,'Plot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value,app.EventChannelSelectionDropDown.Value,app.ThresholdSign);
                end

            end

            if isempty(app.RejectTrialsfromtoEditField.Value)
                app.TempEventRelatedData = [];
            end

            if ~isempty(ThreshTrials)
             % Convert to comma-separated string
                texttoshow = strjoin(string(ThreshTrials), ',');
            else
                texttoshow = '';
            end
            
            app.TextArea.Value = texttoshow;
            
            app.LineHandles2 = findall(app.UIAxes_2,'Type','Line','Tag','Trials');
            app.LineHandles = findall(app.UIAxes,'Tag','TrialsImageSC');

            for k = 1:numel(app.LineHandles2)
                app.LineHandles2(k).PickableParts = 'none';  % clicks pass through to axes
            end
            app.UIAxes_2.ButtonDownFcn = @(src,event) Utility_Trial_Rejection_ButtonDownFunction(app,src,event);
        end

        % Value changed function: PlotTrialsfromtoEditField
        function PlotTrialsfromtoEditFieldValueChanged(app, event)
             %% -------------------- Populate event channel selection -------------------- 
             if isfield(app.Mainapp.Data.Info,'EventRelatedPreprocessing')
                [app.PlotTrialsfromtoEditField.Value] = Event_Module_Check_EventInput(app.PlotTrialsfromtoEditField.Value,app.Mainapp.Data,app.EventChannelSelectionDropDown.Value,"Preprocessed Event Related Data",0);
             else
                [app.PlotTrialsfromtoEditField.Value] = Event_Module_Check_EventInput(app.PlotTrialsfromtoEditField.Value,app.Mainapp.Data,app.EventChannelSelectionDropDown.Value,"Raw Event Related Data",0);
             end

             if ~isempty(app.RejectTrialsfromtoEditField.Value)
                %% Get Data based on Input
                if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                    [~,~,~,ThreshTrials,app.AllYLabels] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.Mainapp.Data.Info.EventRelatedTime,'RejectandPlot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value,app.EventChannelSelectionDropDown.Value,app.ThresholdSign);
                else
                    [~,~,~,ThreshTrials,app.AllYLabels] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.Mainapp.Data.Info.EventRelatedTime,'RejectandPlot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value,app.EventChannelSelectionDropDown.Value,app.ThresholdSign);
                end

            else
                %% Get Data based on Input
                if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                    [~,~,~,ThreshTrials,app.AllYLabels] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.Mainapp.Data.Info.EventRelatedTime,'Plot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value,app.EventChannelSelectionDropDown.Value,app.ThresholdSign);
                else
                    [~,~,~,ThreshTrials,app.AllYLabels] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.Mainapp.Data.Info.EventRelatedTime,'Plot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value,app.EventChannelSelectionDropDown.Value,app.ThresholdSign);
                end

            end
             
            if ~isempty(ThreshTrials)
             % Convert to comma-separated string
                texttoshow = strjoin(string(ThreshTrials), ',');
            else
                texttoshow = '';
            end
            
            app.TextArea.Value = texttoshow;
            
            app.LineHandles2 = findall(app.UIAxes_2,'Type','Line','Tag','Trials');
            app.LineHandles = findall(app.UIAxes,'Tag','TrialsImageSC');

            for k = 1:numel(app.LineHandles2)
                app.LineHandles2(k).PickableParts = 'none';  % clicks pass through to axes
            end
            app.UIAxes_2.ButtonDownFcn = @(src,event) Utility_Trial_Rejection_ButtonDownFunction(app,src,event);
            %app = Utility_Change_Light_Dark_Mode(app,"Event_Trial_Rejection");
        end

        % Value changed function: PlotThresholdCheckBox
        function PlotThresholdCheckBoxValueChanged(app, event)
            %% -------------------- Populate event channel selection -------------------- 
             if isfield(app.Mainapp.Data.Info,'EventRelatedPreprocessing')
                [app.PlotTrialsfromtoEditField.Value] = Event_Module_Check_EventInput(app.PlotTrialsfromtoEditField.Value,app.Mainapp.Data,app.EventChannelSelectionDropDown.Value,"Preprocessed Event Related Data",0);
             else
                [app.PlotTrialsfromtoEditField.Value] = Event_Module_Check_EventInput(app.PlotTrialsfromtoEditField.Value,app.Mainapp.Data,app.EventChannelSelectionDropDown.Value,"Raw Event Related Data",0);
             end

             if ~isempty(app.RejectTrialsfromtoEditField.Value)
                %% Get Data based on Input
                if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                    [~,~,~,ThreshTrials,app.AllYLabels] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.Mainapp.Data.Info.EventRelatedTime,'RejectandPlot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value,app.EventChannelSelectionDropDown.Value,app.ThresholdSign);
                else
                    [~,~,~,ThreshTrials,app.AllYLabels] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.Mainapp.Data.Info.EventRelatedTime,'RejectandPlot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value,app.EventChannelSelectionDropDown.Value,app.ThresholdSign);
                end

            else
                %% Get Data based on Input
                if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                    [~,~,~,ThreshTrials,app.AllYLabels] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.Mainapp.Data.Info.EventRelatedTime,'Plot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value,app.EventChannelSelectionDropDown.Value,app.ThresholdSign);
                else
                    [~,~,~,ThreshTrials,app.AllYLabels] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.Mainapp.Data.Info.EventRelatedTime,'Plot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value,app.EventChannelSelectionDropDown.Value,app.ThresholdSign);
                end

            end
             
            if ~isempty(ThreshTrials)
             % Convert to comma-separated string
                texttoshow = strjoin(string(ThreshTrials), ',');
            else
                texttoshow = '';
            end
            
            app.TextArea.Value = texttoshow;

            if app.PlotThresholdCheckBox.Value == 0
                % manage thresholds
                PosThreshHandle = findobj(app.UIAxes_2, 'Tag', 'PosThresh');
                NegThreshHandle = findobj(app.UIAxes_2, 'Tag', 'NegThresh');
                if ~isempty(PosThreshHandle)
                    delete(PosThreshHandle);
                end
                if ~isempty(NegThreshHandle)
                    delete(NegThreshHandle);
                end
            end

            app.LineHandles2 = findall(app.UIAxes_2,'Type','Line','Tag','Trials');
            app.LineHandles = findall(app.UIAxes,'Tag','TrialsImageSC');

            for k = 1:numel(app.LineHandles2)
                app.LineHandles2(k).PickableParts = 'none';  % clicks pass through to axes
            end

            app.UIAxes_2.ButtonDownFcn = @(src,event) Utility_Trial_Rejection_ButtonDownFunction(app,src,event);

            
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
             %% -------------------- Populate event channel selection -------------------- 
             if isfield(app.Mainapp.Data.Info,'EventRelatedPreprocessing')
                [app.PlotTrialsfromtoEditField.Value] = Event_Module_Check_EventInput(app.PlotTrialsfromtoEditField.Value,app.Mainapp.Data,app.EventChannelSelectionDropDown.Value,"Preprocessed Event Related Data",0);
             else
                [app.PlotTrialsfromtoEditField.Value] = Event_Module_Check_EventInput(app.PlotTrialsfromtoEditField.Value,app.Mainapp.Data,app.EventChannelSelectionDropDown.Value,"Raw Event Related Data",0);
             end

             if ~isempty(app.RejectTrialsfromtoEditField.Value)
                %% Get Data based on Input
                if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                    [~,~,~,ThreshTrials,app.AllYLabels] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.Mainapp.Data.Info.EventRelatedTime,'RejectandPlot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value,app.EventChannelSelectionDropDown.Value,app.ThresholdSign);
                else
                    [~,~,~,ThreshTrials,app.AllYLabels] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.Mainapp.Data.Info.EventRelatedTime,'RejectandPlot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value,app.EventChannelSelectionDropDown.Value,app.ThresholdSign);
                end

            else
                %% Get Data based on Input
                if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                    [~,~,~,ThreshTrials,app.AllYLabels] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.Mainapp.Data.Info.EventRelatedTime,'Plot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value,app.EventChannelSelectionDropDown.Value,app.ThresholdSign);
                else
                    [~,~,~,ThreshTrials,app.AllYLabels] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.Mainapp.Data.Info.EventRelatedTime,'Plot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value,app.EventChannelSelectionDropDown.Value,app.ThresholdSign);
                end

            end
             
            if ~isempty(ThreshTrials)
             % Convert to comma-separated string
                texttoshow = strjoin(string(ThreshTrials), ',');
            else
                texttoshow = '';
            end
            
            app.LineHandles2 = findall(app.UIAxes_2,'Type','Line','Tag','Trials');
            app.LineHandles = findall(app.UIAxes,'Tag','TrialsImageSC');

            for k = 1:numel(app.LineHandles2)
                app.LineHandles2(k).PickableParts = 'none';  % clicks pass through to axes
            end
            app.UIAxes_2.ButtonDownFcn = @(src,event) Utility_Trial_Rejection_ButtonDownFunction(app,src,event);

            app.TextArea.Value = texttoshow;
        end

        % Value changed function: EventChannelSelectionDropDown
        function EventChannelSelectionDropDownValueChanged(app, event)
            
            app.RejectTrialsfromtoEditField.Value = '';

            value = app.EventChannelSelectionDropDown.Value;

            %% -------------------- Extract Event Related Data -------------------- 
            if isfield(app.Mainapp.Data.Info,'EventRelatedPreprocessing')
                [app.Mainapp.Data,~] = Event_Module_Extract_Event_Related_Data(app.Mainapp.Data,app.EventChannelSelectionDropDown.Value,app.TimearoundEvent,app.DataToExtractFromDropDown.Value,"Preprocessed Event Related Data");
            else
                [app.Mainapp.Data,~] = Event_Module_Extract_Event_Related_Data(app.Mainapp.Data,app.EventChannelSelectionDropDown.Value,app.TimearoundEvent,app.DataToExtractFromDropDown.Value,"Raw Event Related Data");
            end

            %% -------------------- Populate event channel selection -------------------- 
            if isfield(app.Mainapp.Data.Info,'EventRelatedPreprocessing')
                [app.PlotTrialsfromtoEditField.Value] = Event_Module_Check_EventInput(app.PlotTrialsfromtoEditField.Value,app.Mainapp.Data,app.EventChannelSelectionDropDown.Value,"Preprocessed Event Related Data",1);
            else
                [app.PlotTrialsfromtoEditField.Value] = Event_Module_Check_EventInput(app.PlotTrialsfromtoEditField.Value,app.Mainapp.Data,app.EventChannelSelectionDropDown.Value,"Raw Event Related Data",1);
            end
            
            %% -------------------- PLOT -------------------- 
            if isfield(app.Mainapp.Data.Info,'EventRelatedPreprocessing')
                [~,~,~,ThreshTrials,app.AllYLabels] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.Mainapp.Data.Info.EventRelatedTime,'Plot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value,app.EventChannelSelectionDropDown.Value,app.ThresholdSign);
            else
                [~,~,~,ThreshTrials,app.AllYLabels] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.Mainapp.Data.Info.EventRelatedTime,'Plot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value,app.EventChannelSelectionDropDown.Value,app.ThresholdSign);
            end
            
            if ~isempty(ThreshTrials)
             % Convert to comma-separated string
                texttoshow = strjoin(string(ThreshTrials), ',');
            else
                texttoshow = '';
            end
            
            app.LineHandles2 = findall(app.UIAxes_2,'Type','Line','Tag','Trials');
            app.LineHandles = findall(app.UIAxes,'Tag','TrialsImageSC');

            for k = 1:numel(app.LineHandles2)
                app.LineHandles2(k).PickableParts = 'none';  % clicks pass through to axes
            end
            app.UIAxes_2.ButtonDownFcn = @(src,event) Utility_Trial_Rejection_ButtonDownFunction(app,src,event);

            app.TextArea.Value = texttoshow;
        end

        % Value changed function: DataToExtractFromDropDown
        function DataToExtractFromDropDownValueChanged(app, event)
            
            spaceindicie = strfind(app.Mainapp.Data.Info.EventRelatedDataTimeRange," ");
            app.TimearoundEvent(1) = str2double(app.Mainapp.Data.Info.EventRelatedDataTimeRange(1:spaceindicie(1)-1));
            app.TimearoundEvent(2) = str2double(app.Mainapp.Data.Info.EventRelatedDataTimeRange(spaceindicie(1)+1:end));

            %% -------------------- Extract Event Related Data -------------------- 
            if isfield(app.Mainapp.Data.Info,'EventRelatedPreprocessing')
                [app.Mainapp.Data,~] = Event_Module_Extract_Event_Related_Data(app.Mainapp.Data,app.EventChannelSelectionDropDown.Value,app.TimearoundEvent,app.DataToExtractFromDropDown.Value,"Preprocessed Event Related Data");
            else
                [app.Mainapp.Data,~] = Event_Module_Extract_Event_Related_Data(app.Mainapp.Data,app.EventChannelSelectionDropDown.Value,app.TimearoundEvent,app.DataToExtractFromDropDown.Value,"Raw Event Related Data");
            end
            %% -------------------- PLOT -------------------- 
            if isfield(app.Mainapp.Data.Info,'EventRelatedPreprocessing')
                [~,~,~,ThreshTrials,app.AllYLabels] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.Mainapp.Data.Info.EventRelatedTime,'Plot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value,app.EventChannelSelectionDropDown.Value,app.ThresholdSign);
            else
                [~,~,~,ThreshTrials,app.AllYLabels] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.Mainapp.Data.Info.EventRelatedTime,'Plot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value,app.EventChannelSelectionDropDown.Value,app.ThresholdSign);
            end
            
            if ~isempty(ThreshTrials)
             % Convert to comma-separated string
                texttoshow = strjoin(string(ThreshTrials), ',');
            else
                texttoshow = '';
            end
            
            app.LineHandles2 = findall(app.UIAxes_2,'Type','Line','Tag','Trials');
            app.LineHandles = findall(app.UIAxes,'Tag','TrialsImageSC');

            for k = 1:numel(app.LineHandles2)
                app.LineHandles2(k).PickableParts = 'none';  % clicks pass through to axes
            end
            app.UIAxes_2.ButtonDownFcn = @(src,event) Utility_Trial_Rejection_ButtonDownFunction(app,src,event);

            app.TextArea.Value = texttoshow;
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
            if value == 1
                app.ThresholdSign(1) = 1;
            else
                if app.CheckBox_2.Value == 1
                    app.ThresholdSign(1) = 0;
                else
                    app.CheckBox.Value = 1;
                    msgbox("Either positive or negative sign must be active!");
                    return;
                end
            end

             %% -------------------- Populate event channel selection -------------------- 
             if isfield(app.Mainapp.Data.Info,'EventRelatedPreprocessing')
                [app.PlotTrialsfromtoEditField.Value] = Event_Module_Check_EventInput(app.PlotTrialsfromtoEditField.Value,app.Mainapp.Data,app.EventChannelSelectionDropDown.Value,"Preprocessed Event Related Data",0);
             else
                [app.PlotTrialsfromtoEditField.Value] = Event_Module_Check_EventInput(app.PlotTrialsfromtoEditField.Value,app.Mainapp.Data,app.EventChannelSelectionDropDown.Value,"Raw Event Related Data",0);
             end

             if ~isempty(app.RejectTrialsfromtoEditField.Value)
                %% Get Data based on Input
                if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                    [~,~,~,ThreshTrials,app.AllYLabels] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.Mainapp.Data.Info.EventRelatedTime,'RejectandPlot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value,app.EventChannelSelectionDropDown.Value,app.ThresholdSign);
                else
                    [~,~,~,ThreshTrials,app.AllYLabels] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.Mainapp.Data.Info.EventRelatedTime,'RejectandPlot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value,app.EventChannelSelectionDropDown.Value,app.ThresholdSign);
                end

            else
                %% Get Data based on Input
                if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                    [~,~,~,ThreshTrials,app.AllYLabels] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.Mainapp.Data.Info.EventRelatedTime,'Plot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value,app.EventChannelSelectionDropDown.Value,app.ThresholdSign);
                else
                    [~,~,~,ThreshTrials,app.AllYLabels] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.Mainapp.Data.Info.EventRelatedTime,'Plot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value,app.EventChannelSelectionDropDown.Value,app.ThresholdSign);
                end

            end
             
            if ~isempty(ThreshTrials)
             % Convert to comma-separated string
                texttoshow = strjoin(string(ThreshTrials), ',');
            else
                texttoshow = '';
            end
            
            app.LineHandles2 = findall(app.UIAxes_2,'Type','Line','Tag','Trials');
            app.LineHandles = findall(app.UIAxes,'Tag','TrialsImageSC');

            for k = 1:numel(app.LineHandles2)
                app.LineHandles2(k).PickableParts = 'none';  % clicks pass through to axes
            end
            app.UIAxes_2.ButtonDownFcn = @(src,event) Utility_Trial_Rejection_ButtonDownFunction(app,src,event);
            
            app.TextArea.Value = texttoshow;
        end

        % Value changed function: CheckBox_2
        function CheckBox_2ValueChanged(app, event)
            value = app.CheckBox_2.Value;
            if value == 1
                app.ThresholdSign(2) = 1;
            else
                if app.CheckBox.Value == 1
                    app.ThresholdSign(2) = 0;
                else
                    app.CheckBox_2.Value = 1;
                    msgbox("Either positive or negative sign must be active!");
                    return;
                end
            end

             %% -------------------- Populate event channel selection -------------------- 
             if isfield(app.Mainapp.Data.Info,'EventRelatedPreprocessing')
                [app.PlotTrialsfromtoEditField.Value] = Event_Module_Check_EventInput(app.PlotTrialsfromtoEditField.Value,app.Mainapp.Data,app.EventChannelSelectionDropDown.Value,"Preprocessed Event Related Data",0);
             else
                [app.PlotTrialsfromtoEditField.Value] = Event_Module_Check_EventInput(app.PlotTrialsfromtoEditField.Value,app.Mainapp.Data,app.EventChannelSelectionDropDown.Value,"Raw Event Related Data",0);
             end

             if ~isempty(app.RejectTrialsfromtoEditField.Value)
                %% Get Data based on Input
                if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                    [~,~,~,ThreshTrials,app.AllYLabels] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.Mainapp.Data.Info.EventRelatedTime,'RejectandPlot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value,app.EventChannelSelectionDropDown.Value,app.ThresholdSign);
                else
                    [~,~,~,ThreshTrials,app.AllYLabels] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.Mainapp.Data.Info.EventRelatedTime,'RejectandPlot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value,app.EventChannelSelectionDropDown.Value,app.ThresholdSign);
                end

            else
                %% Get Data based on Input
                if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                    [~,~,~,ThreshTrials,app.AllYLabels] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.Mainapp.Data.Info.EventRelatedTime,'Plot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value,app.EventChannelSelectionDropDown.Value,app.ThresholdSign);
                else
                    [~,~,~,ThreshTrials,app.AllYLabels] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.Mainapp.Data.Info.EventRelatedTime,'Plot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value,app.EventChannelSelectionDropDown.Value,app.ThresholdSign);
                end

            end
             
            if ~isempty(ThreshTrials)
             % Convert to comma-separated string
                texttoshow = strjoin(string(ThreshTrials), ',');
            else
                texttoshow = '';
            end
            
            app.LineHandles2 = findall(app.UIAxes_2,'Type','Line','Tag','Trials');
            app.LineHandles = findall(app.UIAxes,'Tag','TrialsImageSC');

            for k = 1:numel(app.LineHandles2)
                app.LineHandles2(k).PickableParts = 'none';  % clicks pass through to axes
            end
            app.UIAxes_2.ButtonDownFcn = @(src,event) Utility_Trial_Rejection_ButtonDownFunction(app,src,event);

            app.TextArea.Value = texttoshow;
        end

        % Close request function: TrialRejectionWindowUIFigure
        function TrialRejectionWindowUIFigureCloseRequest(app, event)
            try
                app.Mainapp.EventIndiceRejectionWindow = [];
            catch

            end

            delete(app);
        end

        % Button pushed function: AddTrialsToRejectionButton
        function AddTrialsToRejectionButtonPushed(app, event)
            if isempty(app.TextArea.Value)
                msgbox("No trials exceed thresholds. Please define a threshold so that the 'Trials Crossing Threshold' field is populated.")
                return;
            end
            
            if isempty(app.RejectTrialsfromtoEditField.Value)
                app.RejectTrialsfromtoEditField.Value = convertStringsToChars(app.TextArea.Value{1});
                
            else
                if contains(app.RejectTrialsfromtoEditField.Value,']')
                    app.RejectTrialsfromtoEditField.Value(end) = [];
                    app.RejectTrialsfromtoEditField.Value = [app.RejectTrialsfromtoEditField.Value,',',convertStringsToChars(app.TextArea.Value{1})];
                else
                    app.RejectTrialsfromtoEditField.Value = [app.RejectTrialsfromtoEditField.Value,',',convertStringsToChars(app.TextArea.Value{1})];
                end
            end
            

            %% Now do the same as the reject trials edit field
            if contains(app.RejectTrialsfromtoEditField.Value,',')
                if ~contains(app.RejectTrialsfromtoEditField.Value,'[')
                    app.RejectTrialsfromtoEditField.Value = strcat('[',app.RejectTrialsfromtoEditField.Value);
                end
                if  ~contains(app.RejectTrialsfromtoEditField.Value,']')
                    app.RejectTrialsfromtoEditField.Value = strcat(app.RejectTrialsfromtoEditField.Value,']');
                end
            end
            
            if ~isempty(app.RejectTrialsfromtoEditField.Value)
            %% Get Data based on Input

                if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                    [app.TempEventRelatedData,~,~,ThreshTrials,app.AllYLabels] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.Mainapp.Data.Info.EventRelatedTime,'RejectandPlot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value,app.EventChannelSelectionDropDown.Value,app.ThresholdSign);
                else
                    [app.TempEventRelatedData,~,~,ThreshTrials,app.AllYLabels] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.Mainapp.Data.Info.EventRelatedTime,'RejectandPlot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value,app.EventChannelSelectionDropDown.Value,app.ThresholdSign);
                end

            else
                if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                    [app.TempEventRelatedData,~,~,ThreshTrials,app.AllYLabels] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.Mainapp.Data.Info.EventRelatedTime,'Plot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value,app.EventChannelSelectionDropDown.Value,app.ThresholdSign);
                else
                    [app.TempEventRelatedData,~,~,ThreshTrials,app.AllYLabels] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.Mainapp.Data.Info.EventRelatedTime,'Plot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value,app.PlotTrialsfromtoEditField.Value,app.PlotThresholdCheckBox.Value,app.ThresholdEditField.Value,app.EventChannelSelectionDropDown.Value,app.ThresholdSign);
                end

            end

            if isempty(app.RejectTrialsfromtoEditField.Value)
                app.TempEventRelatedData = [];
            end

            if ~isempty(ThreshTrials)
             % Convert to comma-separated string
                texttoshow = strjoin(string(ThreshTrials), ',');
            else
                texttoshow = '';
            end
            
            app.TextArea.Value = texttoshow;
            
            app.LineHandles2 = findall(app.UIAxes_2,'Type','Line','Tag','Trials');
            app.LineHandles = findall(app.UIAxes,'Tag','TrialsImageSC');

            for k = 1:numel(app.LineHandles2)
                app.LineHandles2(k).PickableParts = 'none';  % clicks pass through to axes
            end
            app.UIAxes_2.ButtonDownFcn = @(src,event) Utility_Trial_Rejection_ButtonDownFunction(app,src,event);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.TrialRejectionWindowUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {822, 822};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {303, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TrialRejectionWindowUIFigure = uifigure('Visible', 'off');
            app.TrialRejectionWindowUIFigure.Name = 'Trial Rejection Window';
            app.TrialRejectionWindowUIFigure.Icon = 'Logo.png';
            app.TrialRejectionWindowUIFigure.CloseRequestFcn = createCallbackFcn(app, @TrialRejectionWindowUIFigureCloseRequest, true);
            app.TrialRejectionWindowUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.TrialRejectionWindowUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TrialRejectionSettingsPanel = uipanel(app.LeftPanel);
            app.TrialRejectionSettingsPanel.Title = 'Trial Rejection Settings';
            app.ChannelofInteresjustforplottLabel = uilabel(app.TrialRejectionSettingsPanel);
            app.ChannelofInteresjustforplottLabel.Text = 'Channel to Plot';
            app.ChannelofInterestDropDown = uidropdown(app.TrialRejectionSettingsPanel);
            app.ChannelofInterestDropDown.Items = {};
            app.ChannelofInterestDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelofInterestDropDownValueChanged, true);
            app.ChannelofInterestDropDown.Value = {};
            app.RejectTrialsfromtoLabel = uilabel(app.TrialRejectionSettingsPanel);
            app.RejectTrialsfromtoLabel.Text = {'Reject Trials (Trial Numbers)'; '(Matlab Expressions)'};
            app.RejectTrialsfromtoEditField = uieditfield(app.TrialRejectionSettingsPanel, 'text');
            app.RejectTrialsfromtoEditField.ValueChangedFcn = createCallbackFcn(app, @RejectTrialsfromtoEditFieldValueChanged, true);
            app.TrialRejectionSettingsLabel = uilabel(app.TrialRejectionSettingsPanel);
            app.TrialRejectionSettingsLabel.Text = 'Trial Rejection Settings';
            app.PlotTrialsfromtoLabel = uilabel(app.TrialRejectionSettingsPanel);
            app.PlotTrialsfromtoLabel.Text = {'Plot Trials (Trial Numbers)'; '(Matlab Expressions)'};
            app.PlotTrialsfromtoEditField = uieditfield(app.TrialRejectionSettingsPanel, 'text');
            app.PlotTrialsfromtoEditField.ValueChangedFcn = createCallbackFcn(app, @PlotTrialsfromtoEditFieldValueChanged, true);
            app.EventChannelSelectionDropDownLabel = uilabel(app.TrialRejectionSettingsPanel);
            app.EventChannelSelectionDropDownLabel.Text = 'Event Channel Selection';
            app.EventChannelSelectionDropDown = uidropdown(app.TrialRejectionSettingsPanel);
            app.EventChannelSelectionDropDown.Items = {};
            app.EventChannelSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @EventChannelSelectionDropDownValueChanged, true);
            app.EventChannelSelectionDropDown.Value = {};
            app.DataToExtractFromDropDownLabel = uilabel(app.TrialRejectionSettingsPanel);
            app.DataToExtractFromDropDownLabel.Text = 'Data To Extract From';
            app.DataToExtractFromDropDown = uidropdown(app.TrialRejectionSettingsPanel);
            app.DataToExtractFromDropDown.Items = {};
            app.DataToExtractFromDropDown.ValueChangedFcn = createCallbackFcn(app, @DataToExtractFromDropDownValueChanged, true);
            app.DataToExtractFromDropDown.Value = {};
            app.ClimfromtoEditFieldLabel = uilabel(app.TrialRejectionSettingsPanel);
            app.ClimfromtoEditFieldLabel.Text = {'Clim'; '[from , to]'};
            app.ClimfromtoEditField = uieditfield(app.TrialRejectionSettingsPanel, 'text');
            app.ClimfromtoEditField.ValueChangedFcn = createCallbackFcn(app, @ClimfromtoEditFieldValueChanged, true);
            app.SaveasnewDatasetButton = uibutton(app.LeftPanel, 'push');
            app.SaveasnewDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @SaveasnewDatasetButtonPushed, true);
            app.SaveasnewDatasetButton.Text = {'Save as '; 'new Dataset'};
            app.CloseButton = uibutton(app.LeftPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.TrialRejectionSettingsPanel_2 = uipanel(app.LeftPanel);
            app.TrialRejectionSettingsPanel_2.Title = 'Trial Rejection Settings';
            app.ThresholdEditFieldLabel = uilabel(app.TrialRejectionSettingsPanel_2);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.TrialRejectionSettingsPanel_2, 'text');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdEditField.Value = '1';
            app.AutomaticOutlierDetectionLabel = uilabel(app.TrialRejectionSettingsPanel_2);
            app.AutomaticOutlierDetectionLabel.Text = 'Automatic Outlier Detection';
            app.PlotThresholdCheckBox = uicheckbox(app.TrialRejectionSettingsPanel_2);
            app.PlotThresholdCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotThresholdCheckBoxValueChanged, true);
            app.PlotThresholdCheckBox.Text = '   Plot Threshold';
            app.PlotThresholdCheckBox.Value = true;
            app.TextArea = uitextarea(app.TrialRejectionSettingsPanel_2);
            app.TrialsCrossingThresholdLabel = uilabel(app.TrialRejectionSettingsPanel_2);
            app.TrialsCrossingThresholdLabel.Text = 'Trials Crossing Threshold';
            app.CheckBox = uicheckbox(app.TrialRejectionSettingsPanel_2);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = ' +';
            app.CheckBox.Value = true;
            app.CheckBox_2 = uicheckbox(app.TrialRejectionSettingsPanel_2);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_2ValueChanged, true);
            app.CheckBox_2.Text = '  -';
            app.CheckBox_2.Value = true;
            app.AddTrialsToRejectionButton = uibutton(app.TrialRejectionSettingsPanel_2, 'push');
            app.AddTrialsToRejectionButton.ButtonPushedFcn = createCallbackFcn(app, @AddTrialsToRejectionButtonPushed, true);
            app.AddTrialsToRejectionButton.Text = 'Add Trials To Rejection';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes_2 = uiaxes(app.RightPanel);
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes = uiaxes(app.RightPanel);
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.TrialRejectionWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Preprocessing_Events_Trial_Rejection_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.TrialRejectionWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.TrialRejectionWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TrialRejectionWindowUIFigure)
        end
    end
end