classdef Events_Spike_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EventRelatedSpikeAnalysisUIFigure  matlab.ui.Figure
        SnapshotMenu                    matlab.ui.container.Menu
        SaveSnapshotofWindowMenu        matlab.ui.container.Menu
        PNGMenu                         matlab.ui.container.Menu
        JPGMenu                         matlab.ui.container.Menu
        SVGMenu_2                       matlab.ui.container.Menu
        MainSpikePlotMenu               matlab.ui.container.Menu
        SpikeRateoverTimeMenu_2         matlab.ui.container.Menu
        SpikeRateoverDepthMenu_2        matlab.ui.container.Menu
        EPSMenu                         matlab.ui.container.Menu
        MainSpikePlotMenu_2             matlab.ui.container.Menu
        SpikeRateoverTimeMenu_3         matlab.ui.container.Menu
        SpikeRateoverDepthMenu_3        matlab.ui.container.Menu
        PlotMenu                        matlab.ui.container.Menu
        Show2DimensionalPlotsMenu       matlab.ui.container.Menu
        Show3DimensionalPlotsMenu       matlab.ui.container.Menu
        ChangePlotPropertiesMenu        matlab.ui.container.Menu
        SpikeDataPlotMenu               matlab.ui.container.Menu
        FontSizeMenu                    matlab.ui.container.Menu
        LineWidthSpikesMenu             matlab.ui.container.Menu
        LineWidthTriggerMenu            matlab.ui.container.Menu
        ColorSpikesMenu                 matlab.ui.container.Menu
        ColorTriggerMenu                matlab.ui.container.Menu
        BackgorundColorMenu             matlab.ui.container.Menu
        XLabelMenu                      matlab.ui.container.Menu
        YLabelMenu                      matlab.ui.container.Menu
        RESETMenu                       matlab.ui.container.Menu
        SpikeRatePlotMenu               matlab.ui.container.Menu
        SpikeRateoverDepthMenu          matlab.ui.container.Menu
        FontSizeMenu_3                  matlab.ui.container.Menu
        BarColorMenu_2                  matlab.ui.container.Menu
        BackgroundColorMenu_2           matlab.ui.container.Menu
        XLabelMenu_3                    matlab.ui.container.Menu
        YLabelMenu_3                    matlab.ui.container.Menu
        RESETMenu_3                     matlab.ui.container.Menu
        SpikeRateoverTimeMenu           matlab.ui.container.Menu
        FontSizeMenu_2                  matlab.ui.container.Menu
        BarColorMenu                    matlab.ui.container.Menu
        BackgroundColorMenu             matlab.ui.container.Menu
        XLabelMenu_2                    matlab.ui.container.Menu
        YLabelMenu_2                    matlab.ui.container.Menu
        RESETMenu_2                     matlab.ui.container.Menu
        SaveCustomeAppearanceMenu       matlab.ui.container.Menu
        DeleteCustomeAppearanceMenu     matlab.ui.container.Menu
        HeatmapBinSizeMenu              matlab.ui.container.Menu
        ChangeBinSizeTimeMenu           matlab.ui.container.Menu
        ChangeBinSizeDepthMenu          matlab.ui.container.Menu
        ExportDataMenu                  matlab.ui.container.Menu
        SpikeRateMenu                   matlab.ui.container.Menu
        AllSpikesMenu                   matlab.ui.container.Menu
        OverTimeMenu                    matlab.ui.container.Menu
        matMenu                         matlab.ui.container.Menu
        txtMenu                         matlab.ui.container.Menu
        csvMenu                         matlab.ui.container.Menu
        xlsxMenu_2                      matlab.ui.container.Menu
        OverChannelMenu                 matlab.ui.container.Menu
        matMenu_2                       matlab.ui.container.Menu
        txtMenu_2                       matlab.ui.container.Menu
        csvMenu_2                       matlab.ui.container.Menu
        xlsxMenu_3                      matlab.ui.container.Menu
        SelectedUnitMenu                matlab.ui.container.Menu
        matMenu_3                       matlab.ui.container.Menu
        txtMenu_3                       matlab.ui.container.Menu
        csvMenu_3                       matlab.ui.container.Menu
        xlsxMenu_4                      matlab.ui.container.Menu
        MainPlotMenu                    matlab.ui.container.Menu
        AllSpikesMenu_2                 matlab.ui.container.Menu
        matMenu_4                       matlab.ui.container.Menu
        txtMenu_4                       matlab.ui.container.Menu
        csvMenu_4                       matlab.ui.container.Menu
        xlsxMenu                        matlab.ui.container.Menu
        SelectedUnitMenu_2              matlab.ui.container.Menu
        matMenu_5                       matlab.ui.container.Menu
        txtMenu_5                       matlab.ui.container.Menu
        csvMenu_5                       matlab.ui.container.Menu
        xlsxMenu_5                      matlab.ui.container.Menu
        Button_3                        matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        CloseButton                     matlab.ui.control.Button
        ChannelSelectionforPlottingEditField  matlab.ui.control.EditField
        ChannelSelectionforPlottingEditFieldLabel  matlab.ui.control.Label
        InformationLabel                matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        SpikeAnalysisOptionsPanel       matlab.ui.container.Panel
        EventChannelSelectionDropDown   matlab.ui.control.DropDown
        EventChannelSelectionDropDownLabel  matlab.ui.control.Label
        DataTypeDropDown                matlab.ui.control.DropDown
        DataTypeLabel                   matlab.ui.control.Label
        SpikeAnalysisOptionsLabel       matlab.ui.control.Label
        TimeWindowSpiketriggredLFPEditField  matlab.ui.control.EditField
        TimeWindowSpiketriggredLFPEditFieldLabel  matlab.ui.control.Label
        BaselineWindowStartStopinsEditField  matlab.ui.control.EditField
        BaselineWindowStartStopinsEditFieldLabel  matlab.ui.control.Label
        EventRangeEditField             matlab.ui.control.EditField
        TriggerNumberSelectionMatlabExpressionsLabel  matlab.ui.control.Label
        SpikeRateNumBinsEditField       matlab.ui.control.EditField
        SpikeRateNumBinsEditFieldLabel  matlab.ui.control.Label
        ClustertoshowDropDown           matlab.ui.control.DropDown
        ClustertoShowDropDownLabel      matlab.ui.control.Label
        BaselineNormalizeCheckBox       matlab.ui.control.CheckBox
        AnalysisTypeDropDown            matlab.ui.control.DropDown
        AnalysisTypeDropDownLabel       matlab.ui.control.Label
        UIAxes_5                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Mainapp = []; % Description
        numCluster = [];
        rgbMatrix = [];
        SpikeBinSettings = [];
        Settings = [];
        SpikeData = [];
        EventRange = [];
        TimearoundEvent = [];
        TwoORThreeD = "TwoD"; % standard 2d plots when possible
        SpikeSorterUsed = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            app.Mainapp = Mainapp;

            movegui(app.EventRelatedSpikeAnalysisUIFigure, 'center');

            app = Utility_Change_Light_Dark_Mode(app,"EventSpikeAnalysis");

            %% -------------------- Set up time -------------------- 
            spaceindicie = strfind(app.Mainapp.Data.Info.EventRelatedDataTimeRange," ");
            app.TimearoundEvent(1) = str2double(app.Mainapp.Data.Info.EventRelatedDataTimeRange(1:spaceindicie(1)-1));
            app.TimearoundEvent(2) = str2double(app.Mainapp.Data.Info.EventRelatedDataTimeRange(spaceindicie(1)+1:end));

            %% -------------------- Extract Event Related Data --------------------
            [app.Mainapp.Data,~] = Event_Module_Extract_Event_Related_Data(app.Mainapp.Data,app.Mainapp.Data.Info.EventChannelNames{1},app.TimearoundEvent,"Raw Data","Raw Event Related Data");
            
            % To extract event related spikes function takes either
            % kilosort or internal as arguments. For Kilosort, spikecluster
            % are also extracted
            if strcmp(app.Mainapp.Data.Info.SpikeType,'Kilosort') || strcmp(app.Mainapp.Data.Info.SpikeType,'SpikeInterface') || strcmp(app.Mainapp.Data.Info.Sorter,'WaveClus')
                app.SpikeSorterUsed = "Kilosort";
            else
                app.SpikeSorterUsed = "Internal";
            end

            [app.Mainapp.Data,~] = Event_Spikes_Extract_Event_Related_Spikes(app.Mainapp.Data,app.SpikeSorterUsed,0,"Raw Event Related Data",app.Mainapp.Data.Info.EventChannelNames{1});
            
            %% -------------------- Set up window -------------------- 
            [app] = Spike_Module_Set_Up_Spike_Analysis_Windows(app,[],"EventWindow");
            
            % %% -------------------- Extract Event Related Data -------------------- 
            % [app.Mainapp.Data,~] = Event_Spikes_Extract_Event_Related_Spikes(app.Mainapp.Data,app.SpikeSorterUsed,0,app.DataTypeDropDown.Value,app.EventChannelSelectionDropDown.Value);

            %% -------------------- Check if prepro event related data found and can be offered -------------------- 
            [texttoshow,Value] = Event_Module_Check_Event_Preprocessing(app.Mainapp.Data,app.EventChannelSelectionDropDown.Value,"Raw Event Related Data");
            app.DataTypeDropDown.Items = {};
            app.DataTypeDropDown.Items = texttoshow;
            app.DataTypeDropDown.Value = Value;

            app.BaselineNormalizeCheckBox.Enable = "on";
            app.BaselineWindowStartStopinsEditField.Enable = "on";

            spaceindicie = find(app.Mainapp.Data.Info.EventRelatedDataTimeRange==' ');
            Timebefore = str2double(app.Mainapp.Data.Info.EventRelatedDataTimeRange(1:spaceindicie(1)-1));
            app.TimeWindowSpiketriggredLFPEditField.Value = strcat('-0.050,',num2str(Timebefore));

            [~,app.ChannelSelectionforPlottingEditField.Value,~,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SpikeBinSettings,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.EventChannelSelectionDropDown.Value,0,app.Mainapp.PreservePlotChannelLocations);
 
            app.UIAxes.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotBackgroundColor;
            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotFontSize;
            
            app.UIAxes_3.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRTimePlotBackgroundColor;
            app.UIAxes_3.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRTimePlotFontSize;

            app.UIAxes_5.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRChannelPlotBackgroundColor;
            app.UIAxes_5.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRChannelPlotFontSize;

            app.TextArea.Value{end+1} = ['Spike Type: ',convertStringsToChars(app.Mainapp.Data.Info.SpikeType)];
            
            if isfield(app.Mainapp.Data.Info,'Sorter')
                app.TextArea.Value{end+1} = ['Sorter: ',convertStringsToChars(app.Mainapp.Data.Info.Sorter)];
            end


        end

        % Value changed function: ClustertoshowDropDown
        function ClustertoshowDropDownValueChanged(app, event)
            app.UIAxes.NextPlot = "replace"; 
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add"; 
            set(app.UIAxes, 'YDir','reverse');
            set(app.UIAxes,'xticklabel',{[]});

            yyaxis(app.UIAxes_3, 'left');
            app.UIAxes_3.NextPlot = "replace"; 
            plot(app.UIAxes_3,0,0);
            app.UIAxes_3.NextPlot = "add";
            set(app.UIAxes_3, 'YDir','reverse');
            ylabel(app.UIAxes_3,"Spike Rate [Hz]")

            yyaxis(app.UIAxes_3, 'right');
            app.UIAxes_3.NextPlot = "replace"; 
            plot(app.UIAxes_3,0,0);
            app.UIAxes_3.NextPlot = "add";
            set(app.UIAxes_3, 'YDir','reverse');
            ylabel(app.UIAxes_3,"Spike Rate [Hz]")

            app.UIAxes_3.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRTimePlotBackgroundColor;
            app.UIAxes_3.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRTimePlotFontSize;

            app.UIAxes_5.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRChannelPlotBackgroundColor;
            app.UIAxes_5.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRChannelPlotFontSize;

            app.UIAxes.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotBackgroundColor;
            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotFontSize;

            [TempData,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SpikeBinSettings,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.EventChannelSelectionDropDown.Value,0,app.Mainapp.PreservePlotChannelLocations);
 
            if ~isempty(TempData)
                app.Mainapp.Data = TempData;
                PreviousChannelDeletetion = 0;
                % If Channel were deleted in previous preprocessing flag this
                % to reset main window parameter after + only allow for prepro
                % view if prepro was generated
                if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                    PreviousChannelDeletetion = 1;
                end
                %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                Utility_Show_Info_Loaded_Data(app.Mainapp);
                %% Wrap up preprocessing
                [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                if strcmp(app.Mainapp.DropDown.Value,'Preprocessed Data')
                    if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                        TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                        % Calculate the absolute differences
                        differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                        
                        % Find the index of the minimum difference
                        [~, app.Mainapp.CurrentTimePoints] = min(differences);
                    end
                end
                %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            end

            if strcmp(app.AnalysisTypeDropDown.Value,"Spike Rate Heatmap") || strcmp(app.AnalysisTypeDropDown.Value,"Spike Triggered Average")
                cb=colorbar('peer',app.UIAxes,'location','WestOutside');
                cb.Color = 'k';              % Sets tick mark and label color to black
                cb.Label.Rotation = 270;
                cb.Label.Color = 'k';        % Sets the color of the label text
                app.UIAxes.XColor = 'k';  
                app.UIAxes.XTickLabelMode = 'auto';  

                if strcmp(app.AnalysisTypeDropDown.Value,"Spike Rate Heatmap")
                    cb.Label.String = "Firing Rate [Hz]";
                elseif strcmp(app.AnalysisTypeDropDown.Value,"Spike Triggered Average")
                    cb.Label.String = "Signal [mV]";
                end
            end

            
            app.UIAxes.Title.Color = 'k';  
            app.UIAxes.YColor = 'k';  
            app.UIAxes.YLabel.Color = 'k';
            %app.UIAxes.YTickLabelMode = 'auto';
            
            if strcmp(app.AnalysisTypeDropDown.Value,"Spike Map") && strcmp(app.ClustertoshowDropDown.Value,'All')  
                colorbar(app.UIAxes,'off');
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.Mainapp.EventKilosortSpikesWindow = [];

            if ~isempty(app.Mainapp.ProbeViewWindowHandle) % delete option from probe view 
                for i = 1:length(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                    if strcmp(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Event Spike Analysis')
                        app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items(i) = [];
                        break;
                    end
                end
            else % if no probe view available
 
            end

            delete(app)
        end

        % Value changed function: SpikeRateNumBinsEditField
        function SpikeRateNumBinsEditFieldValueChanged(app, event)
            % Reset Spike Rate plot in order to show new bin size
            yyaxis(app.UIAxes_3, 'left');
            app.UIAxes_3.NextPlot = "replace"; 
            plot(app.UIAxes_3,0,0);
            app.UIAxes_3.NextPlot = "add";
            set(app.UIAxes_3, 'YDir','reverse');
            ylabel(app.UIAxes_3,"Spike Rate [Hz]")

            yyaxis(app.UIAxes_3, 'right');
            app.UIAxes_3.NextPlot = "replace"; 
            plot(app.UIAxes_3,0,0);
            app.UIAxes_3.NextPlot = "add";
            set(app.UIAxes_3, 'YDir','reverse');
            ylabel(app.UIAxes_3,"Spike Rate [Hz]")

            app.UIAxes_3.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRTimePlotBackgroundColor;
            app.UIAxes_3.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRTimePlotFontSize;

            app.UIAxes_5.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRChannelPlotBackgroundColor;
            app.UIAxes_5.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRChannelPlotFontSize;

            [~,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SpikeBinSettings,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.EventChannelSelectionDropDown.Value,0,app.Mainapp.PreservePlotChannelLocations);
            
            if strcmp(app.AnalysisTypeDropDown.Value,"Spike Rate Heatmap") || strcmp(app.AnalysisTypeDropDown.Value,"Spike Triggered Average")
                cb=colorbar('peer',app.UIAxes,'location','WestOutside');
                cb.Color = 'k';              % Sets tick mark and label color to black
                cb.Label.Rotation = 270;
                cb.Label.Color = 'k';        % Sets the color of the label text
                app.UIAxes.XColor = 'k';  
                app.UIAxes.XTickLabelMode = 'auto';  
                
                if strcmp(app.AnalysisTypeDropDown.Value,"Spike Rate Heatmap")
                    cb.Label.String = "Firing Rate [Hz]";
                elseif strcmp(app.AnalysisTypeDropDown.Value,"Spike Triggered Average")
                    cb.Label.String = "Signal [mV]";
                end
            end

           
            app.UIAxes.Title.Color = 'k';  
            app.UIAxes.YColor = 'k';  
            app.UIAxes.YLabel.Color = 'k';
            %app.UIAxes.YTickLabelMode = 'auto';
         
        end

        % Menu selected function: PNGMenu
        function PNGMenuSelected(app, event)
            [file, path] = uiputfile('*.png', 'Save as');

            exportapp(app.EventRelatedSpikeAnalysisUIFigure,fullfile(path,file)) 
        end

        % Menu selected function: JPGMenu
        function JPGMenuSelected(app, event)
            [file, path] = uiputfile('*.jpg', 'Save as');

            exportapp(app.EventRelatedSpikeAnalysisUIFigure,fullfile(path,file)) 
        end

        % Value changed function: EventRangeEditField
        function EventRangeEditFieldValueChanged(app, event)
            %% -------------------- Populate event channel selection -------------------- 
            [app.EventRangeEditField.Value] = Event_Module_Check_EventInput(app.EventRangeEditField.Value,app.Mainapp.Data,app.EventChannelSelectionDropDown.Value,app.DataTypeDropDown.Value,0);
            
            %% -------------------- Extract Event Related Spikes -------------------- 
            [app.Mainapp.Data,~] = Event_Spikes_Extract_Event_Related_Spikes(app.Mainapp.Data,app.SpikeSorterUsed,0,app.DataTypeDropDown.Value,app.EventChannelSelectionDropDown.Value);
            
            % Reset Spike Rate plot in order to show new bin size
            yyaxis(app.UIAxes_3, 'left');
            app.UIAxes_3.NextPlot = "replace"; 
            plot(app.UIAxes_3,0,0);
            app.UIAxes_3.NextPlot = "add";
            set(app.UIAxes_3, 'YDir','reverse');
            ylabel(app.UIAxes_3,"Spike Rate [Hz]")

            yyaxis(app.UIAxes_3, 'right');
            app.UIAxes_3.NextPlot = "replace"; 
            plot(app.UIAxes_3,0,0);
            app.UIAxes_3.NextPlot = "add";
            set(app.UIAxes_3, 'YDir','reverse');
            ylabel(app.UIAxes_3,"Spike Rate [Hz]")

            app.UIAxes_3.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRTimePlotBackgroundColor;
            app.UIAxes_3.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRTimePlotFontSize;

            app.UIAxes_5.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRChannelPlotBackgroundColor;
            app.UIAxes_5.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRChannelPlotFontSize;

            [app.EventRangeEditField.Value] = Event_Module_Check_EventInput(app.EventRangeEditField.Value,app.Mainapp.Data,app.EventChannelSelectionDropDown.Value,app.DataTypeDropDown.Value,0);

            [TempData,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SpikeBinSettings,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.EventChannelSelectionDropDown.Value,0,app.Mainapp.PreservePlotChannelLocations);
 
            if ~isempty(TempData)
                app.Mainapp.Data = TempData;
                PreviousChannelDeletetion = 0;
                % If Channel were deleted in previous preprocessing flag this
                % to reset main window parameter after + only allow for prepro
                % view if prepro was generated
                if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                    PreviousChannelDeletetion = 1;
                end
                %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                Utility_Show_Info_Loaded_Data(app.Mainapp);
                %% Wrap up preprocessing
                [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                if strcmp(app.Mainapp.DropDown.Value,'Preprocessed Data')
                    if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                        TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                        % Calculate the absolute differences
                        differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                        
                        % Find the index of the minimum difference
                        [~, app.Mainapp.CurrentTimePoints] = min(differences);
                    end
                end
                %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            end
        
            %% Plot spike rate -- have to extract spikes again since they have now to be normalized to event time
            %% Bc of Spike triggered avg this has to be computed again (to get spike time stamps not normalized to event time)
            
            [app.Mainapp.Data,Error] = Event_Spikes_Extract_Event_Related_Spikes(app.Mainapp.Data,app.SpikeSorterUsed,0,app.DataTypeDropDown.Value,app.EventChannelSelectionDropDown.Value);
          
            if Error == 1
                return;
            end

            if strcmp(app.AnalysisTypeDropDown.Value,"Spike Rate Heatmap") || strcmp(app.AnalysisTypeDropDown.Value,"Spike Triggered Average")
                cb=colorbar('peer',app.UIAxes,'location','WestOutside');
                cb.Color = 'k';              % Sets tick mark and label color to black
                cb.Label.Rotation = 270;
                cb.Label.Color = 'k';        % Sets the color of the label text
                app.UIAxes.XColor = 'k';  
                app.UIAxes.XTickLabelMode = 'auto';  
                
                if strcmp(app.AnalysisTypeDropDown.Value,"Spike Rate Heatmap")
                    cb.Label.String = "Firing Rate [Hz]";
                elseif strcmp(app.AnalysisTypeDropDown.Value,"Spike Triggered Average")
                    cb.Label.String = "Signal [mV]";
                end
            end
            
            
            app.UIAxes.Title.Color = 'k';  
            app.UIAxes.YColor = 'k';  
            app.UIAxes.YLabel.Color = 'k';
            %app.UIAxes.YTickLabelMode = 'auto';          

        end

        % Value changed function: AnalysisTypeDropDown
        function AnalysisTypeDropDownValueChanged(app, event)
            app.UIAxes.NextPlot = "replace"; 
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add"; 
            set(app.UIAxes, 'YDir','reverse');
            set(app.UIAxes,'xticklabel',{[]});
            
            app.UIAxes.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotBackgroundColor;
            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotFontSize;

            %% Manage which GUI elements are active
            if strcmp(app.AnalysisTypeDropDown.Value,"Spike Map")
                app.BaselineNormalizeCheckBox.Enable = "on";
                app.BaselineWindowStartStopinsEditField.Enable = "on";
                app.ClustertoshowDropDown.Enable = "on";
                app.TimeWindowSpiketriggredLFPEditField.Enable  = "on";
            elseif strcmp(app.AnalysisTypeDropDown.Value,"Spike Rate Heatmap")
                app.BaselineNormalizeCheckBox.Enable = "on";
                app.BaselineWindowStartStopinsEditField.Enable = "on";
                app.ClustertoshowDropDown.Enable = "on";
                app.TimeWindowSpiketriggredLFPEditField.Enable  = "on";
            elseif strcmp(app.AnalysisTypeDropDown.Value,"Spike Triggered Average")
                app.BaselineNormalizeCheckBox.Enable = "on";
                app.BaselineWindowStartStopinsEditField.Enable = "on";
                app.TimeWindowSpiketriggredLFPEditField.Enable  = "on";
            end

            if strcmp(app.AnalysisTypeDropDown.Value,"Spike Triggered Average")
                [app.Mainapp.Data,~] = Event_Spikes_Extract_Event_Related_Spikes(app.Mainapp.Data,app.SpikeSorterUsed,1,app.DataTypeDropDown.Value,app.EventChannelSelectionDropDown.Value);
            else
                if strcmp(event.PreviousValue,"Spike Triggered Average")
                    [app.Mainapp.Data,~] = Event_Spikes_Extract_Event_Related_Spikes(app.Mainapp.Data,app.SpikeSorterUsed,0,app.DataTypeDropDown.Value,app.EventChannelSelectionDropDown.Value);
                end
            end
            
            [TempData,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SpikeBinSettings,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.EventChannelSelectionDropDown.Value,0,app.Mainapp.PreservePlotChannelLocations);
 
            if ~isempty(TempData)
                app.Mainapp.Data = TempData;
                PreviousChannelDeletetion = 0;
                % If Channel were deleted in previous preprocessing flag this
                % to reset main window parameter after + only allow for prepro
                % view if prepro was generated
                if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                    PreviousChannelDeletetion = 1;
                end
                %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                Utility_Show_Info_Loaded_Data(app.Mainapp);
                %% Wrap up preprocessing
                [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                if strcmp(app.Mainapp.DropDown.Value,'Preprocessed Data')
                    if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                        TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                        % Calculate the absolute differences
                        differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                        
                        % Find the index of the minimum difference
                        [~, app.Mainapp.CurrentTimePoints] = min(differences);
                    end
                end
                %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            end

            if strcmp(app.AnalysisTypeDropDown.Value,"Spike Rate Heatmap") || strcmp(app.AnalysisTypeDropDown.Value,"Spike Triggered Average")
                cb=colorbar('peer',app.UIAxes,'location','WestOutside');
                cb.Color = 'k';              % Sets tick mark and label color to black
                cb.Label.Rotation = 270;
                cb.Label.Color = 'k';        % Sets the color of the label text
                app.UIAxes.XColor = 'k';  
                app.UIAxes.XTickLabelMode = 'auto';  

                if strcmp(app.AnalysisTypeDropDown.Value,"Spike Rate Heatmap")
                    cb.Label.String = "Firing Rate [Hz]";
                elseif strcmp(app.AnalysisTypeDropDown.Value,"Spike Triggered Average")
                    cb.Label.String = "Signal [mV]";
                end
            end

            
            app.UIAxes.Title.Color = 'k';  
            app.UIAxes.YColor = 'k';  
            app.UIAxes.YLabel.Color = 'k';
            %app.UIAxes.YTickLabelMode = 'auto';
            
            app.Mainapp = Utility_Change_Light_Dark_Mode(app.Mainapp,"MainWindow");
        end

        % Value changed function: BaselineNormalizeCheckBox
        function BaselineNormalizeCheckBoxValueChanged(app, event)
            [~,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SpikeBinSettings,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.EventChannelSelectionDropDown.Value,0,app.Mainapp.PreservePlotChannelLocations);
            
            if strcmp(app.AnalysisTypeDropDown.Value,"Spike Rate Heatmap") || strcmp(app.AnalysisTypeDropDown.Value,"Spike Triggered Average")
                cb=colorbar('peer',app.UIAxes,'location','WestOutside');
                cb.Color = 'k';              % Sets tick mark and label color to black
                cb.Label.Rotation = 270;
                cb.Label.Color = 'k';        % Sets the color of the label text
                app.UIAxes.XColor = 'k';  
                app.UIAxes.XTickLabelMode = 'auto';  

                if strcmp(app.AnalysisTypeDropDown.Value,"Spike Rate Heatmap")
                    cb.Label.String = "Firing Rate [Hz]";
                elseif strcmp(app.AnalysisTypeDropDown.Value,"Spike Triggered Average")
                    cb.Label.String = "Signal [mV]";
                end
            end

           
            app.UIAxes.Title.Color = 'k';  
            app.UIAxes.YColor = 'k';  
            app.UIAxes.YLabel.Color = 'k';
            %app.UIAxes.YTickLabelMode = 'auto';
            
        end

        % Value changed function: BaselineWindowStartStopinsEditField
        function BaselineWindowStartStopinsEditFieldValueChanged(app, event)
            [app.BaselineWindowStartStopinsEditField.Value] = Utility_SimpleCheckInputs(app.BaselineWindowStartStopinsEditField.Value,"Two",strcat('-0.2,0.1'),0,0);
            [~,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SpikeBinSettings,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.EventChannelSelectionDropDown.Value,0,app.Mainapp.PreservePlotChannelLocations);
            
            app.UIAxes.Title.Color = 'k';  
            app.UIAxes.YColor = 'k';  
            app.UIAxes.YLabel.Color = 'k';
            %app.UIAxes.YTickLabelMode = 'auto';

            if strcmp(app.AnalysisTypeDropDown.Value,"Spike Rate Heatmap") || strcmp(app.AnalysisTypeDropDown.Value,"Spike Triggered Average")
                cb=colorbar('peer',app.UIAxes,'location','WestOutside');
                cb.Color = 'k';              % Sets tick mark and label color to black
                cb.Label.Rotation = 270;
                cb.Label.Color = 'k';        % Sets the color of the label text
                app.UIAxes.XColor = 'k';  
                app.UIAxes.XTickLabelMode = 'auto';  
                
                if strcmp(app.AnalysisTypeDropDown.Value,"Spike Rate Heatmap")
                    cb.Label.String = "Firing Rate [Hz]";
                elseif strcmp(app.AnalysisTypeDropDown.Value,"Spike Triggered Average")
                    cb.Label.String = "Signal [mV]";
                end
            end
        end

        % Value changed function: ChannelSelectionforPlottingEditField
        function ChannelSelectionforPlottingEditFieldValueChanged(app, event)
            [TempData,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SpikeBinSettings,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.EventChannelSelectionDropDown.Value,0,app.Mainapp.PreservePlotChannelLocations);
 
            if ~isempty(TempData)
                app.Mainapp.Data = TempData;
                PreviousChannelDeletetion = 0;
                % If Channel were deleted in previous preprocessing flag this
                % to reset main window parameter after + only allow for prepro
                % view if prepro was generated
                if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                    PreviousChannelDeletetion = 1;
                end
                %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                Utility_Show_Info_Loaded_Data(app.Mainapp);
                %% Wrap up preprocessing
                [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                if strcmp(app.Mainapp.DropDown.Value,'Preprocessed Data')
                    if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                        TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                        % Calculate the absolute differences
                        differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                        
                        % Find the index of the minimum difference
                        [~, app.Mainapp.CurrentTimePoints] = min(differences);
                    end
                end
                %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            end
        
            %% Plot spike rate -- have to extract spikes again since they have now to be normalized to event time
            %% Bc of Spike triggered avg this has to be computed again (to get spike time stamps not normalized to event time)
            
            [app.Mainapp.Data,Error] = Event_Spikes_Extract_Event_Related_Spikes(app.Mainapp.Data,app.SpikeSorterUsed,0,app.DataTypeDropDown.Value,app.EventChannelSelectionDropDown.Value);
          
            if Error == 1
                return;
            end  
        end

        % Value changed function: TimeWindowSpiketriggredLFPEditField
        function TimeWindowSpiketriggredLFPEditFieldValueChanged(app, event)
            [app.TimeWindowSpiketriggredLFPEditField.Value] = Utility_SimpleCheckInputs(app.TimeWindowSpiketriggredLFPEditField.Value,"Two",strcat('-0.005,0.2'),0,0);
            [TempData,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SpikeBinSettings,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.EventChannelSelectionDropDown.Value,0,app.Mainapp.PreservePlotChannelLocations);
 
            if ~isempty(TempData)
                app.Mainapp.Data = TempData;
                PreviousChannelDeletetion = 0;
                % If Channel were deleted in previous preprocessing flag this
                % to reset main window parameter after + only allow for prepro
                % view if prepro was generated
                if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                    PreviousChannelDeletetion = 1;
                end
                %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                Utility_Show_Info_Loaded_Data(app.Mainapp);
                %% Wrap up preprocessing
                [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                if strcmp(app.Mainapp.DropDown.Value,'Preprocessed Data')
                    if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                        TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                        % Calculate the absolute differences
                        differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                        
                        % Find the index of the minimum difference
                        [~, app.Mainapp.CurrentTimePoints] = min(differences);
                    end
                end
                %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            end
        
            %% Plot spike rate -- have to extract spikes again since they have now to be normalized to event time
            %% Bc of Spike triggered avg this has to be computed again (to get spike time stamps not normalized to event time)
            
            [app.Mainapp.Data,Error] = Event_Spikes_Extract_Event_Related_Spikes(app.Mainapp.Data,app.SpikeSorterUsed,0,app.DataTypeDropDown.Value,app.EventChannelSelectionDropDown.Value);
          
            if Error == 1
                return;
            end  
            
            if strcmp(app.AnalysisTypeDropDown.Value,"Spike Rate Heatmap") || strcmp(app.AnalysisTypeDropDown.Value,"Spike Triggered Average")
                cb=colorbar('peer',app.UIAxes,'location','WestOutside');
                cb.Color = 'k';              % Sets tick mark and label color to black
                cb.Label.Rotation = 270;
                cb.Label.Color = 'k';        % Sets the color of the label text
                app.UIAxes.XColor = 'k';  
                app.UIAxes.XTickLabelMode = 'auto';  

                if strcmp(app.AnalysisTypeDropDown.Value,"Spike Rate Heatmap")
                    cb.Label.String = "Firing Rate [Hz]";
                elseif strcmp(app.AnalysisTypeDropDown.Value,"Spike Triggered Average")
                    cb.Label.String = "Signal [mV]";
                end
            end

            
            app.UIAxes.Title.Color = 'k';  
            app.UIAxes.YColor = 'k';  
            app.UIAxes.YLabel.Color = 'k';
            %app.UIAxes.YTickLabelMode = 'auto';
           
        end

        % Menu selected function: Show2DimensionalPlotsMenu
        function Show2DimensionalPlotsMenuSelected(app, event)
            app.TwoORThreeD = "TwoD";
            
            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";
            set(app.UIAxes, 'YDir','reverse');
            set(app.UIAxes,'xticklabel',{[]});

            app.UIAxes.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotBackgroundColor;
            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotFontSize;

            [TempData,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SpikeBinSettings,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.EventChannelSelectionDropDown.Value,0,app.Mainapp.PreservePlotChannelLocations);
 
            if ~isempty(TempData)
                app.Mainapp.Data = TempData;
                PreviousChannelDeletetion = 0;
                % If Channel were deleted in previous preprocessing flag this
                % to reset main window parameter after + only allow for prepro
                % view if prepro was generated
                if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                    PreviousChannelDeletetion = 1;
                end
                %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                Utility_Show_Info_Loaded_Data(app.Mainapp);
                %% Wrap up preprocessing
                [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                if strcmp(app.Mainapp.DropDown.Value,'Preprocessed Data')
                    if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                        TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                        % Calculate the absolute differences
                        differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                        
                        % Find the index of the minimum difference
                        [~, app.Mainapp.CurrentTimePoints] = min(differences);
                    end
                end
                %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            end
        
            %% Plot spike rate -- have to extract spikes again since they have now to be normalized to event time
            %% Bc of Spike triggered avg this has to be computed again (to get spike time stamps not normalized to event time)
            
            [app.Mainapp.Data,Error] = Event_Spikes_Extract_Event_Related_Spikes(app.Mainapp.Data,app.SpikeSorterUsed,0,app.DataTypeDropDown.Value,app.EventChannelSelectionDropDown.Value);
          
            if Error == 1
                return;
            end  
        end

        % Menu selected function: matMenu
        function matMenuSelected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Events All Spikes Spike Rate over Time ",app.Mainapp.Data.Info.Sorter," ",app.AnalysisTypeDropDown.Value),0);
        end

        % Menu selected function: txtMenu
        function txtMenuSelected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Events All Spikes Spike Rate over Time ",app.Mainapp.Data.Info.Sorter," ",app.AnalysisTypeDropDown.Value),0);
        end

        % Menu selected function: csvMenu
        function csvMenuSelected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".csv",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Events All Spikes Spike Rate over Time ",app.Mainapp.Data.Info.Sorter," ",app.AnalysisTypeDropDown.Value),0);
        end

        % Menu selected function: matMenu_2
        function matMenu_2Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Events All Spikes Spike Rate over Channel ",app.Mainapp.Data.Info.Sorter," ",app.AnalysisTypeDropDown.Value),0);
        end

        % Menu selected function: txtMenu_2
        function txtMenu_2Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Events All Spikes Spike Rate over Channel ",app.Mainapp.Data.Info.Sorter," ",app.AnalysisTypeDropDown.Value),0);
        end

        % Menu selected function: csvMenu_2
        function csvMenu_2Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".csv",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Events All Spikes Spike Rate over Channel ",app.Mainapp.Data.Info.Sorter," ",app.AnalysisTypeDropDown.Value),0);
        end

        % Menu selected function: matMenu_3
        function matMenu_3Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Events Spike Rate Unit ",app.Mainapp.Data.Info.Sorter," ",app.ClustertoshowDropDown.Value),0);
        end

        % Menu selected function: txtMenu_3
        function txtMenu_3Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Events Spike Rate Unit ",app.Mainapp.Data.Info.Sorter," ",app.ClustertoshowDropDown.Value),0);
        end

        % Menu selected function: csvMenu_3
        function csvMenu_3Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".csv",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Events Spike Rate Unit ",app.Mainapp.Data.Info.Sorter," ",app.ClustertoshowDropDown.Value),0);
        end

        % Menu selected function: matMenu_4
        function matMenu_4Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Events All Spike Spike Times ",app.Mainapp.Data.Info.Sorter," ",app.AnalysisTypeDropDown.Value),0);
        end

        % Menu selected function: txtMenu_4
        function txtMenu_4Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Events All Spike Spike Times ",app.Mainapp.Data.Info.Sorter," ",app.AnalysisTypeDropDown.Value),0);
        end

        % Menu selected function: csvMenu_4
        function csvMenu_4Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".csv",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Events All Spike Spike Times ",app.Mainapp.Data.Info.Sorter," ",app.AnalysisTypeDropDown.Value),0);
        end

        % Menu selected function: matMenu_5
        function matMenu_5Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Events Unit Spikes Spike Times ",app.Mainapp.Data.Info.Sorter," ",app.AnalysisTypeDropDown.Value),0);
        end

        % Menu selected function: txtMenu_5
        function txtMenu_5Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Events Unit Spikes Spike Times ",app.Mainapp.Data.Info.Sorter," ",app.AnalysisTypeDropDown.Value),0);
        end

        % Menu selected function: csvMenu_5
        function csvMenu_5Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".csv",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Events Unit Spikes Spike Times ",app.Mainapp.Data.Info.Sorter," ",app.AnalysisTypeDropDown.Value),0);
        end

        % Menu selected function: Show3DimensionalPlotsMenu
        function Show3DimensionalPlotsMenuSelected(app, event)
            app.TwoORThreeD = "ThreeD";
            
            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";
            set(app.UIAxes, 'YDir','reverse');
            set(app.UIAxes,'xticklabel',{[]});

            app.UIAxes.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotBackgroundColor;
            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotFontSize;

            [TempData,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SpikeBinSettings,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.EventChannelSelectionDropDown.Value,0,app.Mainapp.PreservePlotChannelLocations);
 
            if ~isempty(TempData)
                app.Mainapp.Data = TempData;
                PreviousChannelDeletetion = 0;
                % If Channel were deleted in previous preprocessing flag this
                % to reset main window parameter after + only allow for prepro
                % view if prepro was generated
                if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                    PreviousChannelDeletetion = 1;
                end
                %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                Utility_Show_Info_Loaded_Data(app.Mainapp);
                %% Wrap up preprocessing
                [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                if strcmp(app.Mainapp.DropDown.Value,'Preprocessed Data')
                    if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                        TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                        % Calculate the absolute differences
                        differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                        
                        % Find the index of the minimum difference
                        [~, app.Mainapp.CurrentTimePoints] = min(differences);
                    end
                end
                %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            end
        
            %% Plot spike rate -- have to extract spikes again since they have now to be normalized to event time
            %% Bc of Spike triggered avg this has to be computed again (to get spike time stamps not normalized to event time)
            
            [app.Mainapp.Data,Error] = Event_Spikes_Extract_Event_Related_Spikes(app.Mainapp.Data,app.SpikeSorterUsed,0,app.DataTypeDropDown.Value,app.EventChannelSelectionDropDown.Value);
          
            if Error == 1
                return;
            end  
        end

        % Menu selected function: ChangeBinSizeTimeMenu
        function ChangeBinSizeTimeMenuSelected(app, event)
            BinSizeChangeWindow(app,"TimeBins");
        end

        % Menu selected function: ChangeBinSizeDepthMenu
        function ChangeBinSizeDepthMenuSelected(app, event)
            BinSizeChangeWindow(app,"DepthBins");
        end

        % Menu selected function: FontSizeMenu
        function FontSizeMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'fontsize',app.Mainapp,"EventSpikeWindowDataFontSize");
            waitfor(h);

            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotFontSize;

            [TempData,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SpikeBinSettings,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.EventChannelSelectionDropDown.Value,0,app.Mainapp.PreservePlotChannelLocations);
 
            if ~isempty(TempData)
                app.Mainapp.Data = TempData;
                PreviousChannelDeletetion = 0;
                % If Channel were deleted in previous preprocessing flag this
                % to reset main window parameter after + only allow for prepro
                % view if prepro was generated
                if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                    PreviousChannelDeletetion = 1;
                end
                %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                Utility_Show_Info_Loaded_Data(app.Mainapp);
                %% Wrap up preprocessing
                [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                if strcmp(app.Mainapp.DropDown.Value,'Preprocessed Data')
                    if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                        TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                        % Calculate the absolute differences
                        differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                        
                        % Find the index of the minimum difference
                        [~, app.Mainapp.CurrentTimePoints] = min(differences);
                    end
                end
                %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            end
        
            %% Plot spike rate -- have to extract spikes again since they have now to be normalized to event time
            %% Bc of Spike triggered avg this has to be computed again (to get spike time stamps not normalized to event time)
            
            [app.Mainapp.Data,Error] = Event_Spikes_Extract_Event_Related_Spikes(app.Mainapp.Data,app.SpikeSorterUsed,0,app.DataTypeDropDown.Value,app.EventChannelSelectionDropDown.Value);
          
            if Error == 1
                return;
            end  
        end

        % Menu selected function: LineWidthSpikesMenu
        function LineWidthSpikesMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'linewidth',app.Mainapp,"EventSpikeWindowSpikeLineWidth");
            waitfor(h);

            [TempData,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SpikeBinSettings,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.EventChannelSelectionDropDown.Value,0,app.Mainapp.PreservePlotChannelLocations);
 
            if ~isempty(TempData)
                app.Mainapp.Data = TempData;
                PreviousChannelDeletetion = 0;
                % If Channel were deleted in previous preprocessing flag this
                % to reset main window parameter after + only allow for prepro
                % view if prepro was generated
                if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                    PreviousChannelDeletetion = 1;
                end
                %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                Utility_Show_Info_Loaded_Data(app.Mainapp);
                %% Wrap up preprocessing
                [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                if strcmp(app.Mainapp.DropDown.Value,'Preprocessed Data')
                    if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                        TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                        % Calculate the absolute differences
                        differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                        
                        % Find the index of the minimum difference
                        [~, app.Mainapp.CurrentTimePoints] = min(differences);
                    end
                end
                %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            end
        
            %% Plot spike rate -- have to extract spikes again since they have now to be normalized to event time
            %% Bc of Spike triggered avg this has to be computed again (to get spike time stamps not normalized to event time)
            
            [app.Mainapp.Data,Error] = Event_Spikes_Extract_Event_Related_Spikes(app.Mainapp.Data,app.SpikeSorterUsed,0,app.DataTypeDropDown.Value,app.EventChannelSelectionDropDown.Value);
          
            if Error == 1
                return;
            end  
        end

        % Menu selected function: LineWidthTriggerMenu
        function LineWidthTriggerMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'linewidth',app.Mainapp,"EventSpikeWindowTriggerLineWidth");
            waitfor(h);

            [TempData,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SpikeBinSettings,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.EventChannelSelectionDropDown.Value,0,app.Mainapp.PreservePlotChannelLocations);
 
            if ~isempty(TempData)
                app.Mainapp.Data = TempData;
                PreviousChannelDeletetion = 0;
                % If Channel were deleted in previous preprocessing flag this
                % to reset main window parameter after + only allow for prepro
                % view if prepro was generated
                if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                    PreviousChannelDeletetion = 1;
                end
                %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                Utility_Show_Info_Loaded_Data(app.Mainapp);
                %% Wrap up preprocessing
                [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                if strcmp(app.Mainapp.DropDown.Value,'Preprocessed Data')
                    if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                        TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                        % Calculate the absolute differences
                        differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                        
                        % Find the index of the minimum difference
                        [~, app.Mainapp.CurrentTimePoints] = min(differences);
                    end
                end
                %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            end
        
            %% Plot spike rate -- have to extract spikes again since they have now to be normalized to event time
            %% Bc of Spike triggered avg this has to be computed again (to get spike time stamps not normalized to event time)
            
            [app.Mainapp.Data,Error] = Event_Spikes_Extract_Event_Related_Spikes(app.Mainapp.Data,app.SpikeSorterUsed,0,app.DataTypeDropDown.Value,app.EventChannelSelectionDropDown.Value);
          
            if Error == 1
                return;
            end  
        end

        % Menu selected function: ColorSpikesMenu
        function ColorSpikesMenuSelected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotSpikeColor = selectedColor;
            end

            [TempData,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SpikeBinSettings,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.EventChannelSelectionDropDown.Value,0,app.Mainapp.PreservePlotChannelLocations);
 
            if ~isempty(TempData)
                app.Mainapp.Data = TempData;
                PreviousChannelDeletetion = 0;
                % If Channel were deleted in previous preprocessing flag this
                % to reset main window parameter after + only allow for prepro
                % view if prepro was generated
                if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                    PreviousChannelDeletetion = 1;
                end
                %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                Utility_Show_Info_Loaded_Data(app.Mainapp);
                %% Wrap up preprocessing
                [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                if strcmp(app.Mainapp.DropDown.Value,'Preprocessed Data')
                    if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                        TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                        % Calculate the absolute differences
                        differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                        
                        % Find the index of the minimum difference
                        [~, app.Mainapp.CurrentTimePoints] = min(differences);
                    end
                end
                %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            end
        
            %% Plot spike rate -- have to extract spikes again since they have now to be normalized to event time
            %% Bc of Spike triggered avg this has to be computed again (to get spike time stamps not normalized to event time)
            
            [app.Mainapp.Data,Error] = Event_Spikes_Extract_Event_Related_Spikes(app.Mainapp.Data,app.SpikeSorterUsed,0,app.DataTypeDropDown.Value,app.EventChannelSelectionDropDown.Value);
          
            if Error == 1
                return;
            end  
        end

        % Menu selected function: ColorTriggerMenu
        function ColorTriggerMenuSelected(app, event)
             % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotTriggerColor = selectedColor;
            end

            [TempData,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SpikeBinSettings,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.EventChannelSelectionDropDown.Value,0,app.Mainapp.PreservePlotChannelLocations);
 
            if ~isempty(TempData)
                app.Mainapp.Data = TempData;
                PreviousChannelDeletetion = 0;
                % If Channel were deleted in previous preprocessing flag this
                % to reset main window parameter after + only allow for prepro
                % view if prepro was generated
                if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                    PreviousChannelDeletetion = 1;
                end
                %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                Utility_Show_Info_Loaded_Data(app.Mainapp);
                %% Wrap up preprocessing
                [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                if strcmp(app.Mainapp.DropDown.Value,'Preprocessed Data')
                    if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                        TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                        % Calculate the absolute differences
                        differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                        
                        % Find the index of the minimum difference
                        [~, app.Mainapp.CurrentTimePoints] = min(differences);
                    end
                end
                %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            end
        
            %% Plot spike rate -- have to extract spikes again since they have now to be normalized to event time
            %% Bc of Spike triggered avg this has to be computed again (to get spike time stamps not normalized to event time)
            
            [app.Mainapp.Data,Error] = Event_Spikes_Extract_Event_Related_Spikes(app.Mainapp.Data,app.SpikeSorterUsed,0,app.DataTypeDropDown.Value,app.EventChannelSelectionDropDown.Value);
          
            if Error == 1
                return;
            end 
        end

        % Menu selected function: BackgorundColorMenu
        function BackgorundColorMenuSelected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotBackgroundColor = selectedColor;
                app.UIAxes.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotBackgroundColor;

            end
        end

        % Menu selected function: XLabelMenu
        function XLabelMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'xlabel',app.Mainapp,"EventSpikeWindowSpikeXLabel");
            waitfor(h);

            [TempData,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SpikeBinSettings,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.EventChannelSelectionDropDown.Value,0,app.Mainapp.PreservePlotChannelLocations);
 
            if ~isempty(TempData)
                app.Mainapp.Data = TempData;
                PreviousChannelDeletetion = 0;
                % If Channel were deleted in previous preprocessing flag this
                % to reset main window parameter after + only allow for prepro
                % view if prepro was generated
                if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                    PreviousChannelDeletetion = 1;
                end
                %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                Utility_Show_Info_Loaded_Data(app.Mainapp);
                %% Wrap up preprocessing
                [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                if strcmp(app.Mainapp.DropDown.Value,'Preprocessed Data')
                    if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                        TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                        % Calculate the absolute differences
                        differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                        
                        % Find the index of the minimum difference
                        [~, app.Mainapp.CurrentTimePoints] = min(differences);
                    end
                end
                %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            end
        
            %% Plot spike rate -- have to extract spikes again since they have now to be normalized to event time
            %% Bc of Spike triggered avg this has to be computed again (to get spike time stamps not normalized to event time)
            
            [app.Mainapp.Data,Error] = Event_Spikes_Extract_Event_Related_Spikes(app.Mainapp.Data,app.SpikeSorterUsed,0,app.DataTypeDropDown.Value,app.EventChannelSelectionDropDown.Value);
          
            if Error == 1
                return;
            end  
        end

        % Menu selected function: YLabelMenu
        function YLabelMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'ylabel',app.Mainapp,"EventSpikeWindowSpikeYLabel");
            waitfor(h);

            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotFontSize;

            [TempData,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SpikeBinSettings,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.EventChannelSelectionDropDown.Value,0,app.Mainapp.PreservePlotChannelLocations);
 
            if ~isempty(TempData)
                app.Mainapp.Data = TempData;
                PreviousChannelDeletetion = 0;
                % If Channel were deleted in previous preprocessing flag this
                % to reset main window parameter after + only allow for prepro
                % view if prepro was generated
                if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                    PreviousChannelDeletetion = 1;
                end
                %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                Utility_Show_Info_Loaded_Data(app.Mainapp);
                %% Wrap up preprocessing
                [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                if strcmp(app.Mainapp.DropDown.Value,'Preprocessed Data')
                    if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                        TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                        % Calculate the absolute differences
                        differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                        
                        % Find the index of the minimum difference
                        [~, app.Mainapp.CurrentTimePoints] = min(differences);
                    end
                end
                %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            end
        
            %% Plot spike rate -- have to extract spikes again since they have now to be normalized to event time
            %% Bc of Spike triggered avg this has to be computed again (to get spike time stamps not normalized to event time)
            
            [app.Mainapp.Data,Error] = Event_Spikes_Extract_Event_Related_Spikes(app.Mainapp.Data,app.SpikeSorterUsed,0,app.DataTypeDropDown.Value,app.EventChannelSelectionDropDown.Value);
          
            if Error == 1
                return;
            end  
        end

        % Menu selected function: RESETMenu
        function RESETMenuSelected(app, event)
            [app.Mainapp.PlotAppearance] = Organize_Set_Standard_PlotAppearance("InternalEventSpikePlot",app.Mainapp.PlotAppearance);

            app.UIAxes.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotBackgroundColor;
            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotFontSize;
            
            app.UIAxes_3.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRTimePlotBackgroundColor;
            app.UIAxes_3.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRTimePlotFontSize;

            app.UIAxes_5.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRChannelPlotBackgroundColor;
            app.UIAxes_5.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRChannelPlotFontSize;

            [TempData,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SpikeBinSettings,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.EventChannelSelectionDropDown.Value,0,app.Mainapp.PreservePlotChannelLocations);
 
            if ~isempty(TempData)
                app.Mainapp.Data = TempData;
                PreviousChannelDeletetion = 0;
                % If Channel were deleted in previous preprocessing flag this
                % to reset main window parameter after + only allow for prepro
                % view if prepro was generated
                if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                    PreviousChannelDeletetion = 1;
                end
                %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                Utility_Show_Info_Loaded_Data(app.Mainapp);
                %% Wrap up preprocessing
                [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                if strcmp(app.Mainapp.DropDown.Value,'Preprocessed Data')
                    if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                        TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                        % Calculate the absolute differences
                        differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                        
                        % Find the index of the minimum difference
                        [~, app.Mainapp.CurrentTimePoints] = min(differences);
                    end
                end
                %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            end
        
            %% Plot spike rate -- have to extract spikes again since they have now to be normalized to event time
            %% Bc of Spike triggered avg this has to be computed again (to get spike time stamps not normalized to event time)
            
            [app.Mainapp.Data,Error] = Event_Spikes_Extract_Event_Related_Spikes(app.Mainapp.Data,app.SpikeSorterUsed,0,app.DataTypeDropDown.Value,app.EventChannelSelectionDropDown.Value);
            
            app.UIAxes.XColor = 'k';  
            app.UIAxes.YColor = 'k';  
        
            app.UIAxes.XTickLabelMode = 'auto';  
            %app.UIAxes.YTickLabelMode = 'auto';
            app.UIAxes.TickLabelInterpreter = 'none';  % Avoid LaTeX/TeX interpretation if not needed
            %app.UIAxes.FontColor = 'k';  % Tick label color
            
            % Set title, xlabel, ylabel colors
            app.UIAxes.Title.Color = 'k';
            app.UIAxes.XLabel.Color = 'k';
            app.UIAxes.YLabel.Color = 'k';

            if Error == 1
                return;
            end  
        end

        % Menu selected function: FontSizeMenu_2
        function FontSizeMenu_2Selected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes_3,'fontsize',app.Mainapp,"EventSpikeWindowSpikeRateTimeFontSize");
            waitfor(h);

            app.UIAxes_3.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRTimePlotFontSize;
        end

        % Menu selected function: BarColorMenu
        function BarColorMenuSelected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRTimePlotBarColor = selectedColor;
            end

            [~,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SpikeBinSettings,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.EventChannelSelectionDropDown.Value,0,app.Mainapp.PreservePlotChannelLocations);
 
        end

        % Menu selected function: BackgroundColorMenu
        function BackgroundColorMenuSelected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRTimePlotBackgroundColor = selectedColor;
                app.UIAxes_3.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRTimePlotBackgroundColor;
            end
        end

        % Menu selected function: XLabelMenu_2
        function XLabelMenu_2Selected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes_3,'xlabel',app.Mainapp,"EventSpikeWindowSpikeRateTimeXLabel");
            waitfor(h);

            [~,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SpikeBinSettings,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.EventChannelSelectionDropDown.Value,0,app.Mainapp.PreservePlotChannelLocations);
 
        end

        % Menu selected function: YLabelMenu_2
        function YLabelMenu_2Selected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes_3,'fontsize',app.Mainapp,"EventSpikeWindowSpikeRateTimeYLabel");
            waitfor(h);

            [~,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SpikeBinSettings,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.EventChannelSelectionDropDown.Value,0,app.Mainapp.PreservePlotChannelLocations);
 
        end

        % Menu selected function: RESETMenu_2
        function RESETMenu_2Selected(app, event)
            [app.Mainapp.PlotAppearance] = Organize_Set_Standard_PlotAppearance("InternalEventSpikePlot",app.Mainapp.PlotAppearance);
            
            app.UIAxes.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotBackgroundColor;
            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotFontSize;
            
            app.UIAxes_3.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRTimePlotBackgroundColor;
            app.UIAxes_3.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRTimePlotFontSize;

            app.UIAxes_5.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRChannelPlotBackgroundColor;
            app.UIAxes_5.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRChannelPlotFontSize;

            [~,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SpikeBinSettings,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.EventChannelSelectionDropDown.Value,0,app.Mainapp.PreservePlotChannelLocations);
 
        end

        % Menu selected function: FontSizeMenu_3
        function FontSizeMenu_3Selected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes_5,'fontsize',app.Mainapp,"EventSpikeWindowSpikeRateChannelFontSize");
            waitfor(h);

            app.UIAxes_5.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRChannelPlotFontSize;
        end

        % Menu selected function: BarColorMenu_2
        function BarColorMenu_2Selected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRChannelPlotBarColor = selectedColor;
            end

            [~,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SpikeBinSettings,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.EventChannelSelectionDropDown.Value,0,app.Mainapp.PreservePlotChannelLocations);
 
        end

        % Menu selected function: BackgroundColorMenu_2
        function BackgroundColorMenu_2Selected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRChannelPlotBackgroundColor = selectedColor;
                app.UIAxes_5.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRChannelPlotBackgroundColor;
            end
        end

        % Menu selected function: XLabelMenu_3
        function XLabelMenu_3Selected(app, event)
             h = Figure_Property_Change_Window(app.UIAxes_5,'xlabel',app.Mainapp,"EventSpikeWindowSpikeRateChannelXLabel");
            waitfor(h);

            [~,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SpikeBinSettings,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.EventChannelSelectionDropDown.Value,0,app.Mainapp.PreservePlotChannelLocations);
 
        end

        % Menu selected function: YLabelMenu_3
        function YLabelMenu_3Selected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes_5,'ylabel',app.Mainapp,"EventSpikeWindowSpikeRateChannelYLabel");
            waitfor(h);

            [~,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SpikeBinSettings,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.EventChannelSelectionDropDown.Value,0,app.Mainapp.PreservePlotChannelLocations);
 
            
        end

        % Menu selected function: RESETMenu_3
        function RESETMenu_3Selected(app, event)
            [app.Mainapp.PlotAppearance] = Organize_Set_Standard_PlotAppearance("InternalEventSpikePlot",app.Mainapp.PlotAppearance);

            app.UIAxes.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotBackgroundColor;
            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.MainPlotFontSize;
            
            app.UIAxes_3.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRTimePlotBackgroundColor;
            app.UIAxes_3.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRTimePlotFontSize;

            app.UIAxes_5.Color = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRChannelPlotBackgroundColor;
            app.UIAxes_5.FontSize = app.Mainapp.PlotAppearance.InternalEventSpikePlot.SRChannelPlotFontSize;

            [~,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SpikeBinSettings,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.EventChannelSelectionDropDown.Value,0,app.Mainapp.PreservePlotChannelLocations);
            
            
        end

        % Menu selected function: SaveCustomeAppearanceMenu
        function SaveCustomeAppearanceMenuSelected(app, event)
            app.Mainapp.PlotAppearance = Utility_Save_Load_Delete_Plot_Appearance(app.Mainapp.PlotAppearance,app.Mainapp.executableFolder,"Save");
        end

        % Menu selected function: DeleteCustomeAppearanceMenu
        function DeleteCustomeAppearanceMenuSelected(app, event)
            app.Mainapp.PlotAppearance = Utility_Save_Load_Delete_Plot_Appearance(app.Mainapp.PlotAppearance,app.Mainapp.executableFolder,"Delete");
        end

        % Close request function: EventRelatedSpikeAnalysisUIFigure
        function EventRelatedSpikeAnalysisUIFigureCloseRequest(app, event)
            app.Mainapp.EventKilosortSpikesWindow = [];

            if ~isempty(app.Mainapp.ProbeViewWindowHandle) % delete option from probe view 
                for i = 1:length(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                    if strcmp(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Event Spike Analysis')
                        app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items(i) = [];
                        break;
                    end
                end
            else % if no probe view available
 
            end

            delete(app)
            
        end

        % Value changed function: DataTypeDropDown
        function DataTypeDropDownValueChanged(app, event)
   
            
            %% -------------------- Populate event channel selection -------------------- 
            [app.EventRangeEditField.Value] = Event_Module_Check_EventInput(app.EventRangeEditField.Value,app.Mainapp.Data,app.EventChannelSelectionDropDown.Value,app.DataTypeDropDown.Value,1);
            
            %% -------------------- Extract Event Related Data -------------------- 
            [app.Mainapp.Data,~] = Event_Spikes_Extract_Event_Related_Spikes(app.Mainapp.Data,app.SpikeSorterUsed,0,app.DataTypeDropDown.Value,app.EventChannelSelectionDropDown.Value);

            %% Manage Cluster Selection
            if strcmp(app.Mainapp.Data.Info.SpikeType,"Kilosort") || strcmp(app.Mainapp.Data.Info.SpikeType,"Internal") || strcmp(app.Mainapp.Data.Info.SpikeType,"SpikeInterface")
                texttoshow = {};
                
                if strcmp(app.Mainapp.Data.Info.SpikeType,"Internal") && ~strcmp(app.Mainapp.Data.Info.Sorter,'WaveClus')
                    texttoshow{1} = 'Non';
                else
                    texttoshow{1} = 'Non';
                    texttoshow{2} = 'All';
            
                    for i = 1:length(AllUniquecluster)
                        texttoshow{i+2} = num2str(AllUniquecluster(i));
                    end
                end
                app.ClustertoshowDropDown.Items = texttoshow;
            end
            
            app.ClustertoshowDropDown.Value = app.ClustertoshowDropDown.Items{1};

            [TempData,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SpikeBinSettings,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.EventChannelSelectionDropDown.Value,0,app.Mainapp.PreservePlotChannelLocations);
 
            if ~isempty(TempData)
                app.Mainapp.Data = TempData;
                PreviousChannelDeletetion = 0;
                % If Channel were deleted in previous preprocessing flag this
                % to reset main window parameter after + only allow for prepro
                % view if prepro was generated
                if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                    PreviousChannelDeletetion = 1;
                end
                %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                Utility_Show_Info_Loaded_Data(app.Mainapp);
                %% Wrap up preprocessing
                [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                if strcmp(app.Mainapp.DropDown.Value,'Preprocessed Data')
                    if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                        TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                        % Calculate the absolute differences
                        differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                        
                        % Find the index of the minimum difference
                        [~, app.Mainapp.CurrentTimePoints] = min(differences);
                    end
                end
                %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            end
        
            %% Plot spike rate -- have to extract spikes again since they have now to be normalized to event time
            %% Bc of Spike triggered avg this has to be computed again (to get spike time stamps not normalized to event time)
            
            [app.Mainapp.Data,Error] = Event_Spikes_Extract_Event_Related_Spikes(app.Mainapp.Data,app.SpikeSorterUsed,0,app.DataTypeDropDown.Value,app.EventChannelSelectionDropDown.Value);
          
            if Error == 1
                return;
            end

            if strcmp(app.AnalysisTypeDropDown.Value,"Spike Rate Heatmap") || strcmp(app.AnalysisTypeDropDown.Value,"Spike Triggered Average")
                cb=colorbar('peer',app.UIAxes,'location','WestOutside');
                cb.Color = 'k';              % Sets tick mark and label color to black
                cb.Label.Rotation = 270;
                cb.Label.Color = 'k';        % Sets the color of the label text
                app.UIAxes.XColor = 'k';  
                app.UIAxes.XTickLabelMode = 'auto';  
                
                if strcmp(app.AnalysisTypeDropDown.Value,"Spike Rate Heatmap")
                    cb.Label.String = "Firing Rate [Hz]";
                elseif strcmp(app.AnalysisTypeDropDown.Value,"Spike Triggered Average")
                    cb.Label.String = "Signal [mV]";
                end
            end
            
            
            app.UIAxes.Title.Color = 'k';  
            app.UIAxes.YColor = 'k';  
            app.UIAxes.YLabel.Color = 'k';
            %app.UIAxes.YTickLabelMode = 'auto';
   
            %% Manage TextField
            if strcmp(app.Mainapp.Data.Info.SpikeType,"Kilosort") || strcmp(app.Mainapp.Data.Info.SpikeType,"SpikeInterface")
                texttoshow = [strcat("Number of Spikes: ",num2str(length(app.Mainapp.Data.EventRelatedSpikes.SpikeTimes)));...
                strcat("Number of Cluster: ",num2str(length(unique(app.Mainapp.Data.EventRelatedSpikes.SpikeCluster))))];
            else
                if strcmp(app.Mainapp.Data.Info.Sorter,'WaveClus')
                    texttoshow = [strcat("Number of Spikes: ",num2str(length(app.Mainapp.Data.EventRelatedSpikes.SpikeTimes)));...
                    strcat("Number of Cluster: ",num2str(length(unique(app.Mainapp.Data.EventRelatedSpikes.SpikeCluster))))];
                else
                    texttoshow = strcat("Number of Spikes: ",num2str(length(app.Mainapp.Data.EventRelatedSpikes.SpikeTimes)));
                    
                end
            end
            
            app.TextArea.Value = '';
            app.TextArea.Value{1} = convertStringsToChars(texttoshow(1));
            if length(texttoshow)>1
                app.TextArea.Value{2} = convertStringsToChars(texttoshow(2));
            end

            app.TextArea.Value{end+1} = ['Spike Type: ',convertStringsToChars(app.Mainapp.Data.Info.SpikeType)];
            
            if isfield(app.Mainapp.Data.Info,'Sorter')
                app.TextArea.Value{end+1} = ['Sorter: ',convertStringsToChars(app.Mainapp.Data.Info.Sorter)];
            end
        end

        % Value changed function: EventChannelSelectionDropDown
        function EventChannelSelectionDropDownValueChanged(app, event)
            %% -------------------- Check if prepro event related data found and can be offered -------------------- 
            [texttoshow,Value] = Event_Module_Check_Event_Preprocessing(app.Mainapp.Data,app.EventChannelSelectionDropDown.Value,app.DataTypeDropDown.Value);
            app.DataTypeDropDown.Items = {};
            app.DataTypeDropDown.Items = texttoshow;
            app.DataTypeDropDown.Value = Value;
            
            %% -------------------- Populate event channel selection -------------------- 
            [app.EventRangeEditField.Value] = Event_Module_Check_EventInput(app.EventRangeEditField.Value,app.Mainapp.Data,app.EventChannelSelectionDropDown.Value,app.DataTypeDropDown.Value,1);

            [app.Mainapp.Data,~] = Event_Spikes_Extract_Event_Related_Spikes(app.Mainapp.Data,app.SpikeSorterUsed,0,app.DataTypeDropDown.Value,app.EventChannelSelectionDropDown.Value);
            

            %% Manage Cluster Selection
            if strcmp(app.Mainapp.Data.Info.SpikeType,"Kilosort") || strcmp(app.Mainapp.Data.Info.SpikeType,"Internal") || strcmp(app.Mainapp.Data.Info.SpikeType,"SpikeInterface")
                texttoshow = {};
                
                if strcmp(app.Mainapp.Data.Info.SpikeType,"Internal") && ~strcmp(app.Mainapp.Data.Info.Sorter,'WaveClus')
                    texttoshow{1} = 'Non';
                else
                    texttoshow{1} = 'Non';
                    texttoshow{2} = 'All';
                    AllUniquecluster = unique(app.Mainapp.Data.Spikes.SpikeCluster);
                    for i = 1:length(AllUniquecluster)
                        texttoshow{i+2} = num2str(AllUniquecluster(i));
                    end
                end
                app.ClustertoshowDropDown.Items = texttoshow;
            end
            
            app.ClustertoshowDropDown.Value = app.ClustertoshowDropDown.Items{1};

            [TempData,app.ChannelSelectionforPlottingEditField.Value,app.EventRangeEditField.Value,app.SpikeRateNumBinsEditField.Value,app.Mainapp.CurrentPlotData] = Events_Spikes_Manage_Analysis_Plots(app.Mainapp.Data,app.EventRangeEditField.Value,app.UIAxes,app.AnalysisTypeDropDown.Value,app.SpikeRateNumBinsEditField.Value,app.TextArea,app.rgbMatrix,app.numCluster,app.ClustertoshowDropDown.Value,app.ChannelSelectionforPlottingEditField.Value,app.BaselineWindowStartStopinsEditField,app.BaselineNormalizeCheckBox.Value,app.TimeWindowSpiketriggredLFPEditField.Value,app.UIAxes_3,app.UIAxes_5,app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.SpikeBinSettings,app.Mainapp.PlotAppearance,app.Mainapp.ActiveChannel,app.DataTypeDropDown.Value,app.EventChannelSelectionDropDown.Value,0,app.Mainapp.PreservePlotChannelLocations);
 
            if ~isempty(TempData)
                app.Mainapp.Data = TempData;
                PreviousChannelDeletetion = 0;
                % If Channel were deleted in previous preprocessing flag this
                % to reset main window parameter after + only allow for prepro
                % view if prepro was generated
                if isfield(app.Mainapp.Data.Info,"ChannelDeletion")
                    PreviousChannelDeletetion = 1;
                end
                %% Update Textbox "Recording Information" in Main window with new preprocessing infos
                Utility_Show_Info_Loaded_Data(app.Mainapp);
                %% Wrap up preprocessing
                [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],PreviousChannelDeletetion);
                if strcmp(app.Mainapp.DropDown.Value,'Preprocessed Data')
                    if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                        TimeinSecs = app.Mainapp.Data.Time(app.Mainapp.CurrentTimePoints);
                        % Calculate the absolute differences
                        differences = abs(app.Mainapp.Data.TimeDownsampled - TimeinSecs);
                        
                        % Find the index of the minimum difference
                        [~, app.Mainapp.CurrentTimePoints] = min(differences);
                    end
                end
                %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            end
        
            %% Plot spike rate -- have to extract spikes again since they have now to be normalized to event time
            %% Bc of Spike triggered avg this has to be computed again (to get spike time stamps not normalized to event time)
            
            [app.Mainapp.Data,Error] = Event_Spikes_Extract_Event_Related_Spikes(app.Mainapp.Data,app.SpikeSorterUsed,0,app.DataTypeDropDown.Value,app.EventChannelSelectionDropDown.Value);
          
            if Error == 1
                return;
            end

            if strcmp(app.AnalysisTypeDropDown.Value,"Spike Rate Heatmap") || strcmp(app.AnalysisTypeDropDown.Value,"Spike Triggered Average")
                cb=colorbar('peer',app.UIAxes,'location','WestOutside');
                cb.Color = 'k';              % Sets tick mark and label color to black
                cb.Label.Rotation = 270;
                cb.Label.Color = 'k';        % Sets the color of the label text
                app.UIAxes.XColor = 'k';  
                app.UIAxes.XTickLabelMode = 'auto';  
                
                if strcmp(app.AnalysisTypeDropDown.Value,"Spike Rate Heatmap")
                    cb.Label.String = "Firing Rate [Hz]";
                elseif strcmp(app.AnalysisTypeDropDown.Value,"Spike Triggered Average")
                    cb.Label.String = "Signal [mV]";
                end
            end
            
            
            app.UIAxes.Title.Color = 'k';  
            app.UIAxes.YColor = 'k';  
            app.UIAxes.YLabel.Color = 'k';
            %app.UIAxes.YTickLabelMode = 'auto';
   
            %% Manage TextField
            if strcmp(app.Mainapp.Data.Info.SpikeType,"Kilosort") || strcmp(app.Mainapp.Data.Info.SpikeType,"SpikeInterface")
                texttoshow = [strcat("Number of Spikes: ",num2str(length(app.Mainapp.Data.EventRelatedSpikes.SpikeTimes)));...
                strcat("Number of Cluster: ",num2str(length(unique(app.Mainapp.Data.EventRelatedSpikes.SpikeCluster))))];
            else
                if strcmp(app.Mainapp.Data.Info.Sorter,'WaveClus')
                    texttoshow = [strcat("Number of Spikes: ",num2str(length(app.Mainapp.Data.EventRelatedSpikes.SpikeTimes)));...
                    strcat("Number of Cluster: ",num2str(length(unique(app.Mainapp.Data.EventRelatedSpikes.SpikeCluster))))];
                else
                    texttoshow = strcat("Number of Spikes: ",num2str(length(app.Mainapp.Data.EventRelatedSpikes.SpikeTimes)));
                    
                end
            end
            
            app.TextArea.Value = '';
            app.TextArea.Value{1} = convertStringsToChars(texttoshow(1));
            if length(texttoshow)>1
                app.TextArea.Value{2} = convertStringsToChars(texttoshow(2));
            end

            app.TextArea.Value{end+1} = ['Spike Type: ',convertStringsToChars(app.Mainapp.Data.Info.SpikeType)];
            
            if isfield(app.Mainapp.Data.Info,'Sorter')
                app.TextArea.Value{end+1} = ['Sorter: ',convertStringsToChars(app.Mainapp.Data.Info.Sorter)];
            end

            if strcmp(app.AnalysisTypeDropDown.Value,"Spike Map") && strcmp(app.ClustertoshowDropDown.Value,'All')  
                colorbar(app.UIAxes,'off');
            end
        end

        % Menu selected function: xlsxMenu
        function xlsxMenuSelected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".xlsx",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Events All Spike Spike Times ",app.Mainapp.Data.Info.Sorter," ",app.AnalysisTypeDropDown.Value),0);
        end

        % Menu selected function: xlsxMenu_2
        function xlsxMenu_2Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".xlsx",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Events All Spikes Spike Rate over Time ",app.Mainapp.Data.Info.Sorter," ",app.AnalysisTypeDropDown.Value),0);
        end

        % Menu selected function: xlsxMenu_3
        function xlsxMenu_3Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".xlsx",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Events All Spikes Spike Rate over Channel ",app.Mainapp.Data.Info.Sorter," ",app.AnalysisTypeDropDown.Value),0);
        end

        % Menu selected function: xlsxMenu_4
        function xlsxMenu_4Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".xlsx",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Events Spike Rate Unit ",app.Mainapp.Data.Info.Sorter," ",app.ClustertoshowDropDown.Value),0);
        end

        % Menu selected function: xlsxMenu_5
        function xlsxMenu_5Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".xlsx",app.Mainapp.executableFolder,TimeRangePlot,StartTime,strcat("Events Unit Spikes Spike Times ",app.Mainapp.Data.Info.Sorter," ",app.AnalysisTypeDropDown.Value),0);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            Utility_Open_UIAxes_as_Figure(app.Mainapp.Data,app.UIAxes,strcat("EventSpikes ",app.AnalysisTypeDropDown.Value));
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            Utility_Open_UIAxes_as_Figure(app.Mainapp.Data,app.UIAxes_3,"SpikeRateTime");
        end

        % Menu selected function: MainSpikePlotMenu
        function MainSpikePlotMenuSelected(app, event)
            [file, path] = uiputfile('*.svg', 'Save as');
            
            exportgraphics(app.UIAxes, fullfile(path,file), 'ContentType', 'vector');
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            Utility_Open_UIAxes_as_Figure(app.Mainapp.Data,app.UIAxes_5,"SpikeRateChannel");
        end

        % Menu selected function: SpikeRateoverTimeMenu_2
        function SpikeRateoverTimeMenu_2Selected(app, event)
            [file, path] = uiputfile('*.svg', 'Save as');
            
            exportgraphics(app.UIAxes_3, fullfile(path,file), 'ContentType', 'vector');
        end

        % Menu selected function: SpikeRateoverDepthMenu_2
        function SpikeRateoverDepthMenu_2Selected(app, event)
            [file, path] = uiputfile('*.svg', 'Save as');
            
            exportgraphics(app.UIAxes_5, fullfile(path,file), 'ContentType', 'vector');
        end

        % Menu selected function: MainSpikePlotMenu_2
        function MainSpikePlotMenu_2Selected(app, event)
            [file, path] = uiputfile('*.eps', 'Save as');
            
            exportgraphics(app.UIAxes, fullfile(path,file), 'ContentType', 'vector');
        end

        % Menu selected function: SpikeRateoverTimeMenu_3
        function SpikeRateoverTimeMenu_3Selected(app, event)
            [file, path] = uiputfile('*.eps', 'Save as');
            
            exportgraphics(app.UIAxes_3, fullfile(path,file), 'ContentType', 'vector');
        end

        % Menu selected function: SpikeRateoverDepthMenu_3
        function SpikeRateoverDepthMenu_3Selected(app, event)
            [file, path] = uiputfile('*.eps', 'Save as');
            
            exportgraphics(app.UIAxes_5, fullfile(path,file), 'ContentType', 'vector');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EventRelatedSpikeAnalysisUIFigure = uifigure('Visible', 'off');
            app.EventRelatedSpikeAnalysisUIFigure.Name = 'Event Related Spike Analysis';
            app.EventRelatedSpikeAnalysisUIFigure.Icon = 'Logo.png';
            app.EventRelatedSpikeAnalysisUIFigure.CloseRequestFcn = createCallbackFcn(app, @EventRelatedSpikeAnalysisUIFigureCloseRequest, true);
            app.SnapshotMenu = uimenu(app.EventRelatedSpikeAnalysisUIFigure);
            app.SnapshotMenu.Text = '   Snapshot   ';
            app.SaveSnapshotofWindowMenu = uimenu(app.SnapshotMenu);
            app.SaveSnapshotofWindowMenu.Text = 'Save Snapshot of Window';
            app.PNGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.PNGMenu.MenuSelectedFcn = createCallbackFcn(app, @PNGMenuSelected, true);
            app.PNGMenu.Text = 'PNG';
            app.JPGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.JPGMenu.MenuSelectedFcn = createCallbackFcn(app, @JPGMenuSelected, true);
            app.JPGMenu.Text = 'JPG';
            app.SVGMenu_2 = uimenu(app.SaveSnapshotofWindowMenu);
            app.SVGMenu_2.Text = 'SVG';
            app.MainSpikePlotMenu = uimenu(app.SVGMenu_2);
            app.MainSpikePlotMenu.MenuSelectedFcn = createCallbackFcn(app, @MainSpikePlotMenuSelected, true);
            app.MainSpikePlotMenu.Text = 'Main Spike Plot';
            app.SpikeRateoverTimeMenu_2 = uimenu(app.SVGMenu_2);
            app.SpikeRateoverTimeMenu_2.MenuSelectedFcn = createCallbackFcn(app, @SpikeRateoverTimeMenu_2Selected, true);
            app.SpikeRateoverTimeMenu_2.Text = 'Spike Rate over Time';
            app.SpikeRateoverDepthMenu_2 = uimenu(app.SVGMenu_2);
            app.SpikeRateoverDepthMenu_2.MenuSelectedFcn = createCallbackFcn(app, @SpikeRateoverDepthMenu_2Selected, true);
            app.SpikeRateoverDepthMenu_2.Text = 'Spike Rate over Depth';
            app.EPSMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.EPSMenu.Text = 'EPS';
            app.MainSpikePlotMenu_2 = uimenu(app.EPSMenu);
            app.MainSpikePlotMenu_2.MenuSelectedFcn = createCallbackFcn(app, @MainSpikePlotMenu_2Selected, true);
            app.MainSpikePlotMenu_2.Text = 'Main Spike Plot';
            app.SpikeRateoverTimeMenu_3 = uimenu(app.EPSMenu);
            app.SpikeRateoverTimeMenu_3.MenuSelectedFcn = createCallbackFcn(app, @SpikeRateoverTimeMenu_3Selected, true);
            app.SpikeRateoverTimeMenu_3.Text = 'Spike Rate over Time';
            app.SpikeRateoverDepthMenu_3 = uimenu(app.EPSMenu);
            app.SpikeRateoverDepthMenu_3.MenuSelectedFcn = createCallbackFcn(app, @SpikeRateoverDepthMenu_3Selected, true);
            app.SpikeRateoverDepthMenu_3.Text = 'Spike Rate over Depth';
            app.PlotMenu = uimenu(app.EventRelatedSpikeAnalysisUIFigure);
            app.PlotMenu.Text = '   Plot   ';
            app.Show2DimensionalPlotsMenu = uimenu(app.PlotMenu);
            app.Show2DimensionalPlotsMenu.MenuSelectedFcn = createCallbackFcn(app, @Show2DimensionalPlotsMenuSelected, true);
            app.Show2DimensionalPlotsMenu.Text = 'Show 2 Dimensional Plots';
            app.Show3DimensionalPlotsMenu = uimenu(app.PlotMenu);
            app.Show3DimensionalPlotsMenu.MenuSelectedFcn = createCallbackFcn(app, @Show3DimensionalPlotsMenuSelected, true);
            app.Show3DimensionalPlotsMenu.Text = 'Show 3 Dimensional Plots';
            app.ChangePlotPropertiesMenu = uimenu(app.PlotMenu);
            app.ChangePlotPropertiesMenu.Text = 'Change Plot Properties';
            app.SpikeDataPlotMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.SpikeDataPlotMenu.Text = 'Spike Data Plot';
            app.FontSizeMenu = uimenu(app.SpikeDataPlotMenu);
            app.FontSizeMenu.MenuSelectedFcn = createCallbackFcn(app, @FontSizeMenuSelected, true);
            app.FontSizeMenu.Text = 'FontSize';
            app.LineWidthSpikesMenu = uimenu(app.SpikeDataPlotMenu);
            app.LineWidthSpikesMenu.MenuSelectedFcn = createCallbackFcn(app, @LineWidthSpikesMenuSelected, true);
            app.LineWidthSpikesMenu.Text = 'LineWidth Spikes';
            app.LineWidthTriggerMenu = uimenu(app.SpikeDataPlotMenu);
            app.LineWidthTriggerMenu.MenuSelectedFcn = createCallbackFcn(app, @LineWidthTriggerMenuSelected, true);
            app.LineWidthTriggerMenu.Text = 'LineWidth Trigger';
            app.ColorSpikesMenu = uimenu(app.SpikeDataPlotMenu);
            app.ColorSpikesMenu.MenuSelectedFcn = createCallbackFcn(app, @ColorSpikesMenuSelected, true);
            app.ColorSpikesMenu.Text = 'Color Spikes';
            app.ColorTriggerMenu = uimenu(app.SpikeDataPlotMenu);
            app.ColorTriggerMenu.MenuSelectedFcn = createCallbackFcn(app, @ColorTriggerMenuSelected, true);
            app.ColorTriggerMenu.Text = 'Color Trigger';
            app.BackgorundColorMenu = uimenu(app.SpikeDataPlotMenu);
            app.BackgorundColorMenu.MenuSelectedFcn = createCallbackFcn(app, @BackgorundColorMenuSelected, true);
            app.BackgorundColorMenu.Text = 'Backgorund Color';
            app.XLabelMenu = uimenu(app.SpikeDataPlotMenu);
            app.XLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @XLabelMenuSelected, true);
            app.XLabelMenu.Text = 'XLabel';
            app.YLabelMenu = uimenu(app.SpikeDataPlotMenu);
            app.YLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @YLabelMenuSelected, true);
            app.YLabelMenu.Text = 'YLabel';
            app.RESETMenu = uimenu(app.SpikeDataPlotMenu);
            app.RESETMenu.MenuSelectedFcn = createCallbackFcn(app, @RESETMenuSelected, true);
            app.RESETMenu.Text = 'RESET';
            app.SpikeRatePlotMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.SpikeRatePlotMenu.Text = 'Spike Rate Plot';
            app.SpikeRateoverDepthMenu = uimenu(app.SpikeRatePlotMenu);
            app.SpikeRateoverDepthMenu.Text = 'Spike Rate over Depth';
            app.FontSizeMenu_3 = uimenu(app.SpikeRateoverDepthMenu);
            app.FontSizeMenu_3.MenuSelectedFcn = createCallbackFcn(app, @FontSizeMenu_3Selected, true);
            app.FontSizeMenu_3.Text = 'FontSize';
            app.BarColorMenu_2 = uimenu(app.SpikeRateoverDepthMenu);
            app.BarColorMenu_2.MenuSelectedFcn = createCallbackFcn(app, @BarColorMenu_2Selected, true);
            app.BarColorMenu_2.Text = 'Bar Color';
            app.BackgroundColorMenu_2 = uimenu(app.SpikeRateoverDepthMenu);
            app.BackgroundColorMenu_2.MenuSelectedFcn = createCallbackFcn(app, @BackgroundColorMenu_2Selected, true);
            app.BackgroundColorMenu_2.Text = 'Background Color';
            app.XLabelMenu_3 = uimenu(app.SpikeRateoverDepthMenu);
            app.XLabelMenu_3.MenuSelectedFcn = createCallbackFcn(app, @XLabelMenu_3Selected, true);
            app.XLabelMenu_3.Text = 'XLabel';
            app.YLabelMenu_3 = uimenu(app.SpikeRateoverDepthMenu);
            app.YLabelMenu_3.MenuSelectedFcn = createCallbackFcn(app, @YLabelMenu_3Selected, true);
            app.YLabelMenu_3.Text = 'YLabel';
            app.RESETMenu_3 = uimenu(app.SpikeRateoverDepthMenu);
            app.RESETMenu_3.MenuSelectedFcn = createCallbackFcn(app, @RESETMenu_3Selected, true);
            app.RESETMenu_3.Text = 'RESET';
            app.SpikeRateoverTimeMenu = uimenu(app.SpikeRatePlotMenu);
            app.SpikeRateoverTimeMenu.Text = 'Spike Rate over Time';
            app.FontSizeMenu_2 = uimenu(app.SpikeRateoverTimeMenu);
            app.FontSizeMenu_2.MenuSelectedFcn = createCallbackFcn(app, @FontSizeMenu_2Selected, true);
            app.FontSizeMenu_2.Text = 'FontSize';
            app.BarColorMenu = uimenu(app.SpikeRateoverTimeMenu);
            app.BarColorMenu.MenuSelectedFcn = createCallbackFcn(app, @BarColorMenuSelected, true);
            app.BarColorMenu.Text = 'Bar Color';
            app.BackgroundColorMenu = uimenu(app.SpikeRateoverTimeMenu);
            app.BackgroundColorMenu.MenuSelectedFcn = createCallbackFcn(app, @BackgroundColorMenuSelected, true);
            app.BackgroundColorMenu.Text = 'Background Color';
            app.XLabelMenu_2 = uimenu(app.SpikeRateoverTimeMenu);
            app.XLabelMenu_2.MenuSelectedFcn = createCallbackFcn(app, @XLabelMenu_2Selected, true);
            app.XLabelMenu_2.Text = 'XLabel';
            app.YLabelMenu_2 = uimenu(app.SpikeRateoverTimeMenu);
            app.YLabelMenu_2.MenuSelectedFcn = createCallbackFcn(app, @YLabelMenu_2Selected, true);
            app.YLabelMenu_2.Text = 'YLabel';
            app.RESETMenu_2 = uimenu(app.SpikeRateoverTimeMenu);
            app.RESETMenu_2.MenuSelectedFcn = createCallbackFcn(app, @RESETMenu_2Selected, true);
            app.RESETMenu_2.Text = 'RESET';
            app.SaveCustomeAppearanceMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.SaveCustomeAppearanceMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveCustomeAppearanceMenuSelected, true);
            app.SaveCustomeAppearanceMenu.Text = 'Save Custome Appearance';
            app.DeleteCustomeAppearanceMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.DeleteCustomeAppearanceMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteCustomeAppearanceMenuSelected, true);
            app.DeleteCustomeAppearanceMenu.Text = 'Delete Custome Appearance';
            app.HeatmapBinSizeMenu = uimenu(app.PlotMenu);
            app.HeatmapBinSizeMenu.Text = 'Heatmap Bin Size';
            app.ChangeBinSizeTimeMenu = uimenu(app.HeatmapBinSizeMenu);
            app.ChangeBinSizeTimeMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeBinSizeTimeMenuSelected, true);
            app.ChangeBinSizeTimeMenu.Text = 'Change Bin Size Time';
            app.ChangeBinSizeDepthMenu = uimenu(app.HeatmapBinSizeMenu);
            app.ChangeBinSizeDepthMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeBinSizeDepthMenuSelected, true);
            app.ChangeBinSizeDepthMenu.Text = 'Change Bin Size Depth';
            app.ExportDataMenu = uimenu(app.EventRelatedSpikeAnalysisUIFigure);
            app.ExportDataMenu.Text = '   Export Data   ';
            app.SpikeRateMenu = uimenu(app.ExportDataMenu);
            app.SpikeRateMenu.Text = 'Spike Rate';
            app.AllSpikesMenu = uimenu(app.SpikeRateMenu);
            app.AllSpikesMenu.Text = 'All Spikes';
            app.OverTimeMenu = uimenu(app.AllSpikesMenu);
            app.OverTimeMenu.Text = 'Over Time';
            app.matMenu = uimenu(app.OverTimeMenu);
            app.matMenu.MenuSelectedFcn = createCallbackFcn(app, @matMenuSelected, true);
            app.matMenu.Text = '.mat';
            app.txtMenu = uimenu(app.OverTimeMenu);
            app.txtMenu.MenuSelectedFcn = createCallbackFcn(app, @txtMenuSelected, true);
            app.txtMenu.Text = '.txt';
            app.csvMenu = uimenu(app.OverTimeMenu);
            app.csvMenu.MenuSelectedFcn = createCallbackFcn(app, @csvMenuSelected, true);
            app.csvMenu.Text = '.csv';
            app.xlsxMenu_2 = uimenu(app.OverTimeMenu);
            app.xlsxMenu_2.MenuSelectedFcn = createCallbackFcn(app, @xlsxMenu_2Selected, true);
            app.xlsxMenu_2.Text = '.xlsx';
            app.OverChannelMenu = uimenu(app.AllSpikesMenu);
            app.OverChannelMenu.Text = 'Over Channel';
            app.matMenu_2 = uimenu(app.OverChannelMenu);
            app.matMenu_2.MenuSelectedFcn = createCallbackFcn(app, @matMenu_2Selected, true);
            app.matMenu_2.Text = '.mat';
            app.txtMenu_2 = uimenu(app.OverChannelMenu);
            app.txtMenu_2.MenuSelectedFcn = createCallbackFcn(app, @txtMenu_2Selected, true);
            app.txtMenu_2.Text = '.txt';
            app.csvMenu_2 = uimenu(app.OverChannelMenu);
            app.csvMenu_2.MenuSelectedFcn = createCallbackFcn(app, @csvMenu_2Selected, true);
            app.csvMenu_2.Text = '.csv';
            app.xlsxMenu_3 = uimenu(app.OverChannelMenu);
            app.xlsxMenu_3.MenuSelectedFcn = createCallbackFcn(app, @xlsxMenu_3Selected, true);
            app.xlsxMenu_3.Text = '.xlsx';
            app.SelectedUnitMenu = uimenu(app.SpikeRateMenu);
            app.SelectedUnitMenu.Text = 'Selected Unit';
            app.matMenu_3 = uimenu(app.SelectedUnitMenu);
            app.matMenu_3.MenuSelectedFcn = createCallbackFcn(app, @matMenu_3Selected, true);
            app.matMenu_3.Text = '.mat';
            app.txtMenu_3 = uimenu(app.SelectedUnitMenu);
            app.txtMenu_3.MenuSelectedFcn = createCallbackFcn(app, @txtMenu_3Selected, true);
            app.txtMenu_3.Text = '.txt';
            app.csvMenu_3 = uimenu(app.SelectedUnitMenu);
            app.csvMenu_3.MenuSelectedFcn = createCallbackFcn(app, @csvMenu_3Selected, true);
            app.csvMenu_3.Text = '.csv';
            app.xlsxMenu_4 = uimenu(app.SelectedUnitMenu);
            app.xlsxMenu_4.MenuSelectedFcn = createCallbackFcn(app, @xlsxMenu_4Selected, true);
            app.xlsxMenu_4.Text = '.xlsx';
            app.MainPlotMenu = uimenu(app.ExportDataMenu);
            app.MainPlotMenu.Text = 'Main Plot';
            app.AllSpikesMenu_2 = uimenu(app.MainPlotMenu);
            app.AllSpikesMenu_2.Text = 'All Spikes';
            app.matMenu_4 = uimenu(app.AllSpikesMenu_2);
            app.matMenu_4.MenuSelectedFcn = createCallbackFcn(app, @matMenu_4Selected, true);
            app.matMenu_4.Text = '.mat';
            app.txtMenu_4 = uimenu(app.AllSpikesMenu_2);
            app.txtMenu_4.MenuSelectedFcn = createCallbackFcn(app, @txtMenu_4Selected, true);
            app.txtMenu_4.Text = '.txt';
            app.csvMenu_4 = uimenu(app.AllSpikesMenu_2);
            app.csvMenu_4.MenuSelectedFcn = createCallbackFcn(app, @csvMenu_4Selected, true);
            app.csvMenu_4.Text = '.csv';
            app.xlsxMenu = uimenu(app.AllSpikesMenu_2);
            app.xlsxMenu.MenuSelectedFcn = createCallbackFcn(app, @xlsxMenuSelected, true);
            app.xlsxMenu.Text = '.xlsx';
            app.SelectedUnitMenu_2 = uimenu(app.MainPlotMenu);
            app.SelectedUnitMenu_2.Text = 'Selected Unit';
            app.matMenu_5 = uimenu(app.SelectedUnitMenu_2);
            app.matMenu_5.MenuSelectedFcn = createCallbackFcn(app, @matMenu_5Selected, true);
            app.matMenu_5.Text = '.mat';
            app.txtMenu_5 = uimenu(app.SelectedUnitMenu_2);
            app.txtMenu_5.MenuSelectedFcn = createCallbackFcn(app, @txtMenu_5Selected, true);
            app.txtMenu_5.Text = '.txt';
            app.csvMenu_5 = uimenu(app.SelectedUnitMenu_2);
            app.csvMenu_5.MenuSelectedFcn = createCallbackFcn(app, @csvMenu_5Selected, true);
            app.csvMenu_5.Text = '.csv';
            app.xlsxMenu_5 = uimenu(app.SelectedUnitMenu_2);
            app.xlsxMenu_5.MenuSelectedFcn = createCallbackFcn(app, @xlsxMenu_5Selected, true);
            app.xlsxMenu_5.Text = '.xlsx';
            app.UIAxes = uiaxes(app.EventRelatedSpikeAnalysisUIFigure);
            title(app.UIAxes, 'Title')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes_3 = uiaxes(app.EventRelatedSpikeAnalysisUIFigure);
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_5 = uiaxes(app.EventRelatedSpikeAnalysisUIFigure);
            xlabel(app.UIAxes_5, 'X')
            zlabel(app.UIAxes_5, 'Z')
            app.SpikeAnalysisOptionsPanel = uipanel(app.EventRelatedSpikeAnalysisUIFigure);
            app.SpikeAnalysisOptionsPanel.Title = 'Spike Analysis Options';
            app.AnalysisTypeDropDownLabel = uilabel(app.SpikeAnalysisOptionsPanel);
            app.AnalysisTypeDropDownLabel.Text = 'Analysis Type';
            app.AnalysisTypeDropDown = uidropdown(app.SpikeAnalysisOptionsPanel);
            app.AnalysisTypeDropDown.Items = {'Spike Map', 'Spike Rate Heatmap', 'Spike Triggered LFP'};
            app.AnalysisTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @AnalysisTypeDropDownValueChanged, true);
            app.AnalysisTypeDropDown.Value = 'Spike Map';
            app.BaselineNormalizeCheckBox = uicheckbox(app.SpikeAnalysisOptionsPanel);
            app.BaselineNormalizeCheckBox.ValueChangedFcn = createCallbackFcn(app, @BaselineNormalizeCheckBoxValueChanged, true);
            app.BaselineNormalizeCheckBox.Text = {'  Baseline '; '  Normalize'};
            app.ClustertoShowDropDownLabel = uilabel(app.SpikeAnalysisOptionsPanel);
            app.ClustertoShowDropDownLabel.Text = 'Cluster to Show';
            app.ClustertoshowDropDown = uidropdown(app.SpikeAnalysisOptionsPanel);
            app.ClustertoshowDropDown.Items = {};
            app.ClustertoshowDropDown.ValueChangedFcn = createCallbackFcn(app, @ClustertoshowDropDownValueChanged, true);
            app.ClustertoshowDropDown.Value = {};
            app.SpikeRateNumBinsEditFieldLabel = uilabel(app.SpikeAnalysisOptionsPanel);
            app.SpikeRateNumBinsEditFieldLabel.Text = {'Spike Rate'; 'Num Bins'};
            app.SpikeRateNumBinsEditField = uieditfield(app.SpikeAnalysisOptionsPanel, 'text');
            app.SpikeRateNumBinsEditField.ValueChangedFcn = createCallbackFcn(app, @SpikeRateNumBinsEditFieldValueChanged, true);
            app.SpikeRateNumBinsEditField.Value = '150';
            app.TriggerNumberSelectionMatlabExpressionsLabel = uilabel(app.SpikeAnalysisOptionsPanel);
            app.TriggerNumberSelectionMatlabExpressionsLabel.Text = {'Trigger Number'; 'Selection (Matlab Expressions)'};
            app.EventRangeEditField = uieditfield(app.SpikeAnalysisOptionsPanel, 'text');
            app.EventRangeEditField.ValueChangedFcn = createCallbackFcn(app, @EventRangeEditFieldValueChanged, true);
            app.BaselineWindowStartStopinsEditFieldLabel = uilabel(app.SpikeAnalysisOptionsPanel);
            app.BaselineWindowStartStopinsEditFieldLabel.Text = {'Baseline Window'; '[Start,Stop] in s'};
            app.BaselineWindowStartStopinsEditField = uieditfield(app.SpikeAnalysisOptionsPanel, 'text');
            app.BaselineWindowStartStopinsEditField.ValueChangedFcn = createCallbackFcn(app, @BaselineWindowStartStopinsEditFieldValueChanged, true);
            app.TimeWindowSpiketriggredLFPEditFieldLabel = uilabel(app.SpikeAnalysisOptionsPanel);
            app.TimeWindowSpiketriggredLFPEditFieldLabel.Text = {'Time Window '; 'Spike triggred LFP'};
            app.TimeWindowSpiketriggredLFPEditField = uieditfield(app.SpikeAnalysisOptionsPanel, 'text');
            app.TimeWindowSpiketriggredLFPEditField.ValueChangedFcn = createCallbackFcn(app, @TimeWindowSpiketriggredLFPEditFieldValueChanged, true);
            app.TimeWindowSpiketriggredLFPEditField.Value = '-0.005,0.2';
            app.SpikeAnalysisOptionsLabel = uilabel(app.SpikeAnalysisOptionsPanel);
            app.SpikeAnalysisOptionsLabel.Text = 'Spike Analysis Options';
            app.DataTypeLabel = uilabel(app.SpikeAnalysisOptionsPanel);
            app.DataTypeLabel.Text = 'Event Data Type';
            app.DataTypeDropDown = uidropdown(app.SpikeAnalysisOptionsPanel);
            app.DataTypeDropDown.Items = {};
            app.DataTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DataTypeDropDownValueChanged, true);
            app.DataTypeDropDown.Value = {};
            app.EventChannelSelectionDropDownLabel = uilabel(app.SpikeAnalysisOptionsPanel);
            app.EventChannelSelectionDropDownLabel.Text = 'Event Channel Selection';
            app.EventChannelSelectionDropDown = uidropdown(app.SpikeAnalysisOptionsPanel);
            app.EventChannelSelectionDropDown.Items = {};
            app.EventChannelSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @EventChannelSelectionDropDownValueChanged, true);
            app.EventChannelSelectionDropDown.Value = {};
            app.TextArea = uitextarea(app.EventRelatedSpikeAnalysisUIFigure);
            app.InformationLabel = uilabel(app.EventRelatedSpikeAnalysisUIFigure);
            app.InformationLabel.Text = 'Information';
            app.ChannelSelectionforPlottingEditFieldLabel = uilabel(app.EventRelatedSpikeAnalysisUIFigure);
            app.ChannelSelectionforPlottingEditFieldLabel.Enable = 'off';
            app.ChannelSelectionforPlottingEditFieldLabel.Visible = 'off';
            app.ChannelSelectionforPlottingEditFieldLabel.Text = {'Channel Selection'; 'for Plotting'};
            app.ChannelSelectionforPlottingEditField = uieditfield(app.EventRelatedSpikeAnalysisUIFigure, 'text');
            app.ChannelSelectionforPlottingEditField.ValueChangedFcn = createCallbackFcn(app, @ChannelSelectionforPlottingEditFieldValueChanged, true);
            app.ChannelSelectionforPlottingEditField.Editable = 'off';
            app.ChannelSelectionforPlottingEditField.Enable = 'off';
            app.ChannelSelectionforPlottingEditField.Visible = 'off';
            app.CloseButton = uibutton(app.EventRelatedSpikeAnalysisUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.Button = uibutton(app.EventRelatedSpikeAnalysisUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'Open_Figure_Icon.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.EventRelatedSpikeAnalysisUIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'Open_Figure_Icon.png';
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.EventRelatedSpikeAnalysisUIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = 'Open_Figure_Icon.png';
            app.Button_3.Text = '';
            % Show the figure after all components are created
            app.EventRelatedSpikeAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Events_Spike_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.EventRelatedSpikeAnalysisUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.EventRelatedSpikeAnalysisUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EventRelatedSpikeAnalysisUIFigure)
        end
    end
end