classdef Extract_Trigger_Times < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ExtractTriggerTimesWindowUIFigure  matlab.ui.Figure
        DeleteEventTriggerIndicesMenu   matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        RightPanel                      matlab.ui.container.Panel
        TriggerTimeExtractionSettingsLabel  matlab.ui.control.Label
        LoadCostumeTriggerIdentityButton  matlab.ui.control.Button
        PlotInputChannelButton          matlab.ui.control.Button
        StartEventExtractionButton      matlab.ui.control.Button
        SetFoldermanuallyButton         matlab.ui.control.Button
        EventRelatedTrialsDataSettingsLabel  matlab.ui.control.Label
        NrInputChinfolderEditField      matlab.ui.control.EditField
        NrInputChannelfoundinFolderLabel  matlab.ui.control.Label
        RecordingSystem                 matlab.ui.control.EditField
        RecordingSystemLabel            matlab.ui.control.Label
        EventExtractionSettingsPanel    matlab.ui.container.Panel
        Panel                           matlab.ui.container.Panel
        EventTypeDropDown               matlab.ui.control.DropDown
        TriggerTypeDropDownLabel        matlab.ui.control.Label
        InputChannelSelectionLabel      matlab.ui.control.Label
        TriggerTresholdVLabel           matlab.ui.control.Label
        AnalogThresholdVEditField       matlab.ui.control.EditField
        InputChannelSelectionEditField  matlab.ui.control.EditField
        CombineInputChannelButton       matlab.ui.control.Button
        SelectEventChannelTypeLabel     matlab.ui.control.Label
        FileTypeDropDown                matlab.ui.control.DropDown
        FilePathLabel                   matlab.ui.control.Label
        InfoandFilesinPathLabel         matlab.ui.control.Label
        TextArea_2                      matlab.ui.control.TextArea
        TextArea                        matlab.ui.control.TextArea
        ExtractEventRelatedDataPanel    matlab.ui.container.Panel
        Panel_2                         matlab.ui.container.Panel
        TimeWindowAfterEventssEditField  matlab.ui.control.EditField
        TimeWindowAfterTriggersEditFieldLabel  matlab.ui.control.Label
        TimeWindowBeforeEventssEditField  matlab.ui.control.EditField
        TimeWindowBeforeTriggersEditFieldLabel  matlab.ui.control.Label
        CloseButton                     matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Mainapp = [] % Description
        Path = [];
        EventInfo = [];
        RHDAllChannelData = [];
        ExtractedRHDEventsFlag = 0;
        FilesinPath = [];
        TimearoundEvent = [];
        maxInputSelection = [];
        AdditionalEventInfo = [];
        NeoEventStartTimeStamp = [];
        ShowChannelPlotWindow = [];

        CostumeChannelIdentityInfo = [];
        CostumeFolder = [];

        EventsToCombine = [];
        CombineEventsWindow = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            % Moce this window in the middle of thescreen
            movegui(app.ExtractTriggerTimesWindowUIFigure, 'center');
            
            app.Mainapp = Mainapp;

            app = Utility_Change_Light_Dark_Mode(app,"ExtractEvents");
            
            if strcmp(app.Mainapp.Data.Info.RecordingType,"NEO")
                app.Path = app.Mainapp.Data.Info.NeoSaveFolder;
            else
                app.Path = app.Mainapp.Data.Info.Data_Path;
            end

            %% Initiate Event Extraction Panel -- Basic Parameters

            app.TextArea.Value = app.Path;
            pause(0.2);

            if strcmp(app.Mainapp.Data.Info.RecordingType,"IntanDat")            
                app.RecordingSystem.Value = "Intan .dat files";
                app.TextArea_2.Value = "Intan Event Data is extracted. Please wait until this text area shows infos about the found events.";
                app.TextArea.Value = strcat("Extracting Events from Path: ",app.Path);
                pause(0.1);
            elseif strcmp(app.Mainapp.Data.Info.RecordingType,"IntanRHD")            
                app.RecordingSystem.Value = "Intan .rhd files";
                app.TextArea_2.Value = "Intan Event Data is extracted. Please wait until this text area shows infos about the found events.";
                app.TextArea.Value = strcat("Extracting Events from Path: ",app.Path);
                pause(0.1);
            elseif strcmp(app.Mainapp.Data.Info.RecordingType,"Neuralynx") %%%% Not implemented yet
                app.SelectEventChannelTypeLabel.Text = "Neuralynx Event Type";
                app.TextArea_2.Value = "Neuralynx Event Data is extracted. Please wait until this text area shows infos about the found events.";
                app.TextArea.Value = strcat("Extracting Events from Path: ",app.Path);
                pause(0.1);
                app.FileTypeDropDown.Items = {'Events.nev'};
                app.AnalogThresholdVEditField.Value = "";
                app.AnalogThresholdVEditField.Enable = "off";
                app.RecordingSystem.Value = "Neuralynx files";
            elseif strcmp(app.Mainapp.Data.Info.RecordingType,"Open Ephys")
                app.SelectEventChannelTypeLabel.Text = "Record Node";
                app.InputChannelSelectionLabel.Text = "Event Line Nr. to Extract";
                app.TextArea_2.Value = "Open Ephys Event Data is extracted. Please wait until this text area shows infos about the found events.";
                app.TextArea.Value = strcat("Extracting Events from Path: ",app.Path);
                pause(0.1);
                app.TriggerTresholdVLabel.Text = "State (Either 1 or 0)";
                app.RecordingSystem.Value = "Open Ephys files";
                app.AnalogThresholdVEditField.Value = "1";
                app.InputChannelSelectionLabel.Text = "Event Lines";
            elseif strcmp(app.Mainapp.Data.Info.RecordingType,"Spike2")
                app.RecordingSystem.Value = "Spike2 Recording";
                app.InputChannelSelectionLabel.Text = "Data Channel containing Events";
                app.TextArea_2.Value = "Spike2 Event Data is extracted. Please wait until this text area shows infos about the found events.";
                app.TextArea.Value = strcat("Extracting Events from Path: ",app.Path);
                pause(0.1);
            elseif strcmp(app.Mainapp.Data.Info.RecordingType,"TDT Tank Data")
                app.RecordingSystem.Value = "Spike2 Recording";
                app.InputChannelSelectionLabel.Text = "Event Channel Selection";
                app.TextArea_2.Value = "TDT event data is extracted. Please wait until this text area shows infos about the found events.";
                app.TextArea.Value = strcat("Extracting Events from Path: ",app.Path);
                pause(0.1);
            elseif strcmp(app.Mainapp.Data.Info.RecordingType,"NEO")
                app.AnalogThresholdVEditField.Value = "";
                app.AnalogThresholdVEditField.Enable = "off";
                pause(0.1);
            elseif strcmp(app.Mainapp.Data.Info.RecordingType,"SpikeInterface Maxwell MEA .h5")
                app.RecordingSystem.Value = "SpikeInterface Maxwell MEA .h5";
                app.AnalogThresholdVEditField.Value = "";
                app.AnalogThresholdVEditField.Enable = "off";
                app.FileTypeDropDown.Items = {};
                app.FileTypeDropDown.Items{1} = 'Bits Group';
                app.FileTypeDropDown.Items{2} = 'Events Dataset';

                app.EventTypeDropDown.Items = {};
                app.EventTypeDropDown.Items{1} = 'Trigger Onset';
                app.EventTypeDropDown.Items{2} = 'Trigger Offset';
                
                pause(0.1);
            end

            if ~strcmp(app.Mainapp.Data.Info.RecordingType,"IntanDat") && ~strcmp(app.Mainapp.Data.Info.RecordingType,"IntanRHD") && ~strcmp(app.Mainapp.Data.Info.RecordingType,"Spike2") && ~strcmp(app.Mainapp.Data.Info.RecordingType,"SpikeInterface Maxwell MEA .h5")
                app.EventTypeDropDown.Enable = "off";
            end
            
            %% Function to determine which event channel area vailable in folder based on names of folder components
            if strcmp(app.Mainapp.Data.Info.RecordingType,"NEO")
                app.NeoEventStartTimeStamp = [];
                [app.NeoEventStartTimeStamp,app.EventInfo,FilePaths,texttoshow] = Extract_Events_Module_NEO_Determine_Available_EventChannel(app.Mainapp.Data,app.Path);
                if isempty(app.EventInfo)
                    FileEndingsExist = 0;
                else
                    FileEndingsExist = 1;
                end
                Info = [];
            else
                [app.EventInfo,FileEndingsExist,FilePaths,texttoshow,Info] = Extract_Events_Module_Determine_Available_EventChannel(app.Mainapp.Data,app.Path,app.FileTypeDropDown.Value);
            end
            
            app.AdditionalEventInfo = Info;
            
            Extract_Events_Module_Set_Up_Window(app,app.Mainapp.Data,app.EventInfo,app.Path,FilePaths,FileEndingsExist,texttoshow,"Initial",Info);
            
            if isfield(app.Mainapp.Data.Info,'CutStart') || isfield(app.Mainapp.Data.Info,'CutEnd')
                app.TextArea.Value = [app.TextArea.Value;" ";"Warning: Time of Recording was cut! Potentially, not all events present in the data can be extracted!"];
            end
            
            if iscell(app.TextArea_2.Value)
                try
                    TextToShow = cell(size(app.TextArea_2.Value,1)+1,1);

                    for i = 1:length(app.TextArea_2.Value)
                        TextToShow{i+1+4}=app.TextArea_2.Value{i};
                    end
                    
                    if strcmp(app.Mainapp.Data.Info.RecordingType,"NEO")
                        TextToShow{1} = 'Recording extracted with NEO detected. On startup, this window automatically searches for the folder that was created when extracting a recording with NEO besides the recording folder (or file) called "Folder/filename Neo SaveFile". If the extracted recording contains events, a .mat file called "NEO_Saved_EventData.mat" is saved in this location with all event trigger information. This of course only works as long as you didnt move or delete the folder created with NEO from the original location it was created in. If the files from NEO where deleted or you cant find them anymore, use the "Set Folder Manually" button to select the recording folder, which extracts and saves events again in the automatically created folder. If the event file was found and successfully loaded, contents are shown below.';
                    else
                        TextToShow{1} = 'This window automatically searches for event channel in the raw recording that was selected when amplifier data was extracted.';
                        TextToShow{2} = 'First select the event input channel type on the left. These depend on the recording format and can be analog/digital channel, recording nodes etc. Each event input channel type can contain one or more individual event channels that can in turn each contain multiple triggers.';
                        TextToShow{3} = 'The "Input Channel Selection" below is automatically populated with numbers from 1 to the number of event channels found of the input type specified before. In this field you can delete individual channel numbers so that triggers are not extracted from all found event channel. In doubt, click the "Plot Input Channel" button to view the signal within each event channel. If the path to recording containing your events changed and was not found automatically, you can specify a new folder to search for with the "Set Folder Manually" button.';
                        TextToShow{4} = 'When analyzing event related signals, event related data is extracted on the fly based on the trigger times extracted here. Since for event related analysis, a certain time window before and after each trigger is analysed. The time before and after each trigger can be set below.';
                        TextToShow{5} = 'If you have a recording setup that records trigger for multiple purposes (like responses or stimulations) within the same event input channel, you can load a .txt or .csv file containing the identity of each trigger to split up all triggers found into separate event channel. Click the "Load Custom Trigger Identity" button for more information.';
                    end
                    
                    app.TextArea_2.Value = TextToShow;                       
                end
            end
            
            if ~strcmp(app.Mainapp.Data.Info.RecordingType,"Open Ephys")
                app.EventInfo.InputChannelNumber = app.InputChannelSelectionEditField.Value;
            else
                app.AdditionalEventInfo.InputChannelNumber = app.InputChannelSelectionEditField.Value;
            end
            
            app.TimearoundEvent(1) = str2double(app.TimeWindowBeforeEventssEditField.Value);
            app.TimearoundEvent(2) = str2double(app.TimeWindowAfterEventssEditField.Value);
            % Save all selected event channelnr for comparisons later
            app.maxInputSelection = app.InputChannelSelectionEditField.Value;
        end

        % Value changed function: FileTypeDropDown
        function FileTypeDropDownValueChanged(app, event)
            % Reset channel combination and costum trigger selection (and close window)
            try
                if ~isempty(app.CombineEventsWindow)
                    delete(app.CombineEventsWindow);
                end
            catch
            end

            app.CostumeChannelIdentityInfo = [];
            app.CombineEventsWindow = [];

            app.TextArea_2.Value = "Event Data is extracted. Please wait until this text area shows infos about the found events.";
            pause(0.1);
            
            if strcmp(app.Mainapp.Data.Info.RecordingType,"NEO")
                app.NeoEventStartTimeStamp = [];
                [app.NeoEventStartTimeStamp,app.EventInfo,FilePaths,texttoshow] = Extract_Events_Module_NEO_Determine_Available_EventChannel(app.Mainapp.Data,app.Path);
                if isempty(app.EventInfo)
                    FileEndingsExist = 0;
                else
                    FileEndingsExist = 1;
                end
                Info = [];
            elseif strcmp(app.Mainapp.Data.Info.RecordingType,"TDT Tank Data")
                if isempty(app.EventInfo)
                    msgbox("No event data found. If it was shown before, close this window and open again!");
                    return;
                end
                
                [~,FileEndingsExist,FilePaths,texttoshow,Info] = Extract_Events_Module_Determine_Available_EventChannel(app.Mainapp.Data,app.Path,'NoDataExtraction');
                
            else
                [app.EventInfo,FileEndingsExist,FilePaths,texttoshow,Info] = Extract_Events_Module_Determine_Available_EventChannel(app.Mainapp.Data,app.Path,app.FileTypeDropDown.Value);
            end

            %% TDT
            if isfield(app.EventInfo,'Type')
                if ~contains(app.EventInfo.Type,'streams')
                    app.AnalogThresholdVEditField.Enable = "off";
                end
                app.EventTypeDropDown.Enable = "on";

                if strcmp(app.FileTypeDropDown.Value,"TDT Trigger epocs:Evnt") || strcmp(app.FileTypeDropDown.Value,"TDT Trigger epocs:Brst") || strcmp(app.FileTypeDropDown.Value,"TDT Trigger epocs:Tria") || strcmp(app.FileTypeDropDown.Value,"TDT Trigger epocs:Stro") || strcmp(app.FileTypeDropDown.Value,"TDT Trigger epocs:Tick")
                    app.EventTypeDropDown.Enable = "on";
                    app.EventTypeDropDown.Items = {};
                    app.EventTypeDropDown.Items{1} = 'Trigger Onset';
                    app.EventTypeDropDown.Items{2} = 'Trigger Offset';
                else
                    app.EventTypeDropDown.Enable = "off";
                end 
            end

            app.AdditionalEventInfo = Info;
            Extract_Events_Module_Set_Up_Window(app,app.Mainapp.Data,app.EventInfo,app.Path,FilePaths,FileEndingsExist,texttoshow,"ChangedEventChannelType",Info);

            app.maxInputSelection = app.InputChannelSelectionEditField.Value;

            if iscell(app.TextArea_2.Value)
                try
                    TextToShow = cell(size(app.TextArea_2.Value,1)+1,1);

                    for i = 1:length(app.TextArea_2.Value)
                        TextToShow{i+1+4}=app.TextArea_2.Value{i};
                    end
                    
                    if strcmp(app.Mainapp.Data.Info.RecordingType,"NEO")
                        TextToShow{1} = 'Wcording extracted with NEO detected. On startup, this window automatically searches for the folder that was created when extracting a recording with NEO besides the recording folder (or file) called "Folder/filename Neo SaveFile". If the extracted recording contains events, a .mat file called "NEO_Saved_EventData.mat" is saved in this location with all event trigger information. This of course only works as long as you didnt move or delete the folder created with NEO from the original location it was created in. If the files from NEO where deleted or you cant find them anymore, use the "Set Folder Manually" to select the recording folder, which extracts and saves events again in the automatically created folder. If the event file was found and successfully loaded, contents are shown below.'; 
                    else
                        TextToShow{1} = 'This window automatically searches for event channel in the raw recording that was selected when amplifier data was extracted.';
                        TextToShow{2} = 'First select the event input channel type on the left. These depend on the recording format and can be analog/digital channel, recording nodes etc. Each event input channel type can contain one or more individual event channels that can in turn each contain multiple triggers.';
                        TextToShow{3} = 'The "Input Channel Selection" below is automatically populated with numbers from 1 to the number of event channels found of the input type specified before. In this field you can delete individual channel numbers so that triggers are not extracted from all found event channel. In doubt, click the "Plot Input Channel" button to view the signal within each event channel. If the path to recording containing your events changed and was not found automatically, you can specify a new folder to search for with the "Set Folder Manually" button.';
                        TextToShow{4} = 'When analyzing event related signals, event related data is extracted on the fly based on the trigger times extracted here. Since for event related analysis, a certain time window before and after each trigger is analysed. The time before and after each trigger can be set below.';
                        TextToShow{5} = 'If you have a recording setup that records trigger for multiple purposes (like responses or stimulations) within the same event input channel, you can load a .txt or .csv file containing the identity of each trigger to split up all triggers found into separate event channel. Click the "Load Custom Trigger Identity" button for more information.';
                    end
                    
                    app.TextArea_2.Value = TextToShow;                       
                end
            end

            if ~isempty(app.CostumeChannelIdentityInfo)
                if iscell(app.TextArea_2.Value)
                    try                 
                        if strcmp(app.Mainapp.Data.Info.RecordingType,"NEO")
                            TextToShow{1} = 'Recording extracted with NEO detected. On startup, this window automatically searches for the folder that was created when extracting a recording with NEO besides the recording folder (or file) called "Folder/filename Neo SaveFile". If the extracted recording contains events, a .mat file called "NEO_Saved_EventData.mat" is saved in this location with all event trigger information. This of course only works as long as you didnt move or delete the folder created with NEO from the original location it was created in. If you should have moved the file, select the "Set Folder Manually". Select the same folder/file you would for data extraction. If the files from NEO where deleted or you cant find them anymore, also use the "Set Folder Manually" to again select the recording folder, which extracts and saved events again in the automatically created folder. If the event file was found and successfully loaded, contents are shown below.'; 
                        else
                            TextToShow{1} = 'This window automatically searches for event channel in the raw recording that was selected when amplifier data was extracted. First select the input channel type on the left, i.e. digital or analog event input channel. Those types can contain one or more continous event channel signals with individual triggers (i.e. Intan recordings) or sample numbers of each trigger (Open Ephys). The "Input Channel Selection" below that is automatically populated with numbers from 1 to the number of event channel found of the type specified before. In this field you can delete channel numbers so that triggers are not extracted from all found event channel. In doubt, click the "Plot Input Channel" button to view the signal within each event channel. If the path to recording containing your events changed and was not found automatically, you can specify a new folder to search for with the "Set Folder Manually" button. When analyzing event related signals, event related data (or trial data) is extracted on the fly based on the trigger times extracted here which each trigger representing a trial. Trial time around each trigger is set in the panel below. If only one event channel exists that contains different trigger conditions (like different stimulation frequencies), you can load a .txt or .csv file containing the identity of each trigger to split up all triggers found into separate event channel. Click the button for more information.';
                        end

                        app.TextArea_2.Value = TextToShow;                       
                    end
                end
    
                if length(strsplit(app.InputChannelSelectionEditField.Value,',')) > 1
                    app.InputChannelSelectionEditField.BackgroundColor = [1, 0, 0];
                end
            end
            
            if ~strcmp(app.Mainapp.Data.Info.RecordingType,"Open Ephys")
                app.EventInfo.InputChannelNumber = app.InputChannelSelectionEditField.Value;
            else
                app.AdditionalEventInfo.InputChannelNumber = app.InputChannelSelectionEditField.Value;
            end
        end

        % Button pushed function: SetFoldermanuallyButton
        function SetFoldermanuallyButtonPushed(app, event)
            app.CombineEventsWindow = [];

            try
                if ~isempty(app.CombineEventsWindow)
                    delete(app.CombineEventsWindow);
                end
            catch

            end

            if ~isempty(app.EventInfo)
                app.EventInfo = [];
            end
            %% Get user selcted folder and save as app.selectedFolder
            % Prompt the user to select a folder
            NewPath = uigetdir(app.Mainapp.executableFolder,'Select a folder containing event data');
            if NewPath == 0
                disp('No folder selected. Exiting.');
                return;
            end

            app.TextArea_2.Value = "";

            app.Path = NewPath;
            
            app = Utility_Change_Light_Dark_Mode(app,"ExtractEvents");

            %% Function to determine which event channel area vailable in folder based on names of folder components
    	    %% NEO
            if strcmp(app.Mainapp.Data.Info.RecordingType,"NEO")
                
                % search for already saved event data
                eventdatapath = strcat(app.Path," NEO SaveFile\NEO_Saved_EventData.mat");
                
                FoundEventData = 0;
                if isfile(eventdatapath)
                    try
                        load(eventdatapath,'event_samples')
                        FoundEventData = 1;
                        disp(strcat("Found event data saved in folder ",eventdatapath))
                    catch
                        FoundEventData = 0;
                        disp(strcat("No event data was found in searched folder ",eventdatapath))
                    end
                end
                
                if FoundEventData == 0
                    app.TextArea.Value = strcat("Extracting just event data from reccording in folder/file: ",app.Path, " with Neo. Please press Enter in the python console that opens to close it when it tells you that it finished (or in case of an error message). Otherwise Matlab will stay unrespsonsive.");
                    app.TextArea_2.Value = Utility_Extract_Contents_of_Folder(app.Path);
                    pause(0.1);
    
                    AskForNEOParameterWindow = AskForNeoParameter(app.Mainapp, app);
        
                    uiwait(AskForNEOParameterWindow.NeoParameterUIFigure);
                    % Wait for the app to close
    
                    if isvalid(AskForNEOParameterWindow)
                        if isempty(AskForNEOParameterWindow.NEOParameter)
                            msgbox("No NEO parameter found. Returning.")
                            delete(AskForNEOParameterWindow);
                            return;
                        else
                            NEOParameter = AskForNEOParameterWindow.NEOParameter;
                            delete(AskForNEOParameterWindow);
                        end
                    end
    
                    % start python script etc
                    Extract_Events_Module_Start_NEO_EventExtraction(app.Mainapp.Data,app.Mainapp.executableFolder,app.Path,NEOParameter);
                end

                app.NeoEventStartTimeStamp = [];
                [app.NeoEventStartTimeStamp,app.EventInfo,FilePaths,texttoshow] = Extract_Events_Module_NEO_Determine_Available_EventChannel(app.Mainapp.Data,app.Path);
                if isempty(app.EventInfo)
                    FileEndingsExist = 0;
                else
                    FileEndingsExist = 1;
                end
                Info = [];  
            else
                [app.EventInfo,FileEndingsExist,FilePaths,texttoshow,Info] = Extract_Events_Module_Determine_Available_EventChannel(app.Mainapp.Data,app.Path,app.FileTypeDropDown.Value);
            end

            app.AdditionalEventInfo = Info;
            Extract_Events_Module_Set_Up_Window(app,app.Mainapp.Data,app.EventInfo,app.Path,FilePaths,FileEndingsExist,texttoshow,"Initial",Info);

            app.maxInputSelection = app.InputChannelSelectionEditField.Value;

            if isfield(app.Mainapp.Data.Info,'CutStart') || isfield(app.Mainapp.Data.Info,'CutEnd')
                app.TextArea.Value = [app.TextArea.Value;" ";"Warning: Time of Recording was cut! Potentially, not all events present in the data can be extracted!"];
            end
            
            if iscell(app.TextArea_2.Value)
                try
                    TextToShow = cell(size(app.TextArea_2.Value,1)+1,1);

                    for i = 1:length(app.TextArea_2.Value)
                        TextToShow{i+1+4}=app.TextArea_2.Value{i};
                    end
                    
                    if strcmp(app.Mainapp.Data.Info.RecordingType,"NEO")
                        TextToShow{1} = 'Recording extracted with NEO detected. On startup, this window automatically searches for the folder that was created when extracting a recording with NEO besides the recording folder (or file) called "Folder/filename Neo SaveFile". If the extracted recording contains events, a .mat file called "NEO_Saved_EventData.mat" is saved in this location with all event trigger information. This of course only works as long as you didnt move or delete the folder created with NEO from the original location it was created in. If the files from NEO where deleted or you cant find them anymore, use the "Set Folder Manually" to select the recording folder, which extracts and saves events again in the automatically created folder. If the event file was found and successfully loaded, contents are shown below.'; 
                    else
                        TextToShow{1} = 'This window automatically searches for event channel in the raw recording that was selected when amplifier data was extracted.';
                        TextToShow{2} = 'First select the event input channel type on the left. These depend on the recording format and can be analog/digital channel, recording nodes etc. Each event input channel type can contain one or more individual event channels that can in turn each contain multiple triggers.';
                        TextToShow{3} = 'The "Input Channel Selection" below is automatically populated with numbers from 1 to the number of event channels found of the input type specified before. In this field you can delete individual channel numbers so that triggers are not extracted from all found event channel. In doubt, click the "Plot Input Channel" button to view the signal within each event channel. If the path to recording containing your events changed and was not found automatically, you can specify a new folder to search for with the "Set Folder Manually" button.';
                        TextToShow{4} = 'When analyzing event related signals, event related data is extracted on the fly based on the trigger times extracted here. Since for event related analysis, a certain time window before and after each trigger is analysed. The time before and after each trigger can be set below.';
                        TextToShow{5} = 'If you have a recording setup that records trigger for multiple purposes (like responses or stimulations) within the same event input channel, you can load a .txt or .csv file containing the identity of each trigger to split up all triggers found into separate event channel. Click the "Load Custom Trigger Identity" button for more information.';
                    end
                    
                    app.TextArea_2.Value = TextToShow;                       
                end
            end

            if ~strcmp(app.Mainapp.Data.Info.RecordingType,"Open Ephys")
                app.EventInfo.InputChannelNumber = app.InputChannelSelectionEditField.Value;
            else
                app.AdditionalEventInfo.InputChannelNumber = app.InputChannelSelectionEditField.Value;
            end
        end

        % Button pushed function: PlotInputChannelButton
        function PlotInputChannelButtonPushed(app, event)
            
            if isempty(app.EventInfo)
                msgbox("Error: No Event Channel found.");
                return;
            end

            %% For Single Intan RHD Files
            if strcmp(app.Mainapp.Data.Info.RecordingType,"IntanRHD") && app.ExtractedRHDEventsFlag == 0
            
                [RhdFilePaths] = LoadIntanRHDFiles(app.Path);
                RhdFilePaths = convertStringsToChars(RhdFilePaths);

                LastDashIndex = find(RhdFilePaths == '\');
                RHDPath = RhdFilePaths(1:LastDashIndex(end));
                RHDFiles = RhdFilePaths(LastDashIndex(end)+1:end);

                app.TextArea_2.Value = "Extracting Event Input Channel from RHD file";

                [~,app.RHDAllChannelData.board_adc_data,~,app.RHDAllChannelData.board_dig_in_data,~,~,~,app.RHDAllChannelData.aux_input_data,~] = Intan_RHD2000_Data_Extraction (RHDFiles,RHDPath,"Extracting",app.TextArea_2);
            
                app.ExtractedRHDEventsFlag = 1;

                app.TextArea_2.Value = "Event Channel Extraction to show Event Channel Plots finished.";

            end

            app.ShowChannelPlotWindow = Show_Event_Channel_Window(app.Mainapp,app);
        end

        % Button pushed function: StartEventExtractionButton
        function StartEventExtractionButtonPushed(app, event)
            
            try
                if ~isempty(app.CombineEventsWindow)
                    delete(app.CombineEventsWindow);
                end
            catch

            end

            if isempty(app.EventInfo) 
                msgbox("Error: No Event Channel found.");
                return;
            end
            
            if ~isempty(app.CostumeChannelIdentityInfo)
                if length(strsplit(app.InputChannelSelectionEditField.Value,',')) > 1
                   msgbox("Error: Only ONE event channel allowed when custom trigger identities where loaded! Please adjust the 'Input Channel Selection' field accordingly.");
                   return;
                end
            end

            if strcmp(app.Mainapp.Data.Info.RecordingType,"IntanDat") || strcmp(app.Mainapp.Data.Info.RecordingType,"Spike2") || strcmp(app.Mainapp.Data.Info.RecordingType,"IntanRHD")
                app.EventInfo.EventType = app.EventTypeDropDown.Value;
            end

            if strcmp(app.Mainapp.Data.Info.RecordingType,"TDT Tank Data")
                app.AdditionalEventInfo.State = app.EventTypeDropDown.Value;
            end

            % Split the string based on the delimiter ','
            IndividualStrings = strsplit(app.InputChannelSelectionEditField.Value, ',');
            
            % Convert the cell array of strings to a numeric vector
            InputChannelSelection = str2double(IndividualStrings);
            SelectedNode = [];
            if strcmp(app.Mainapp.Data.Info.RecordingType,"Open Ephys")
                for i = 1:length(app.FileTypeDropDown.Items)
                    if strcmp(app.FileTypeDropDown.Items{i},app.FileTypeDropDown.Value)
                        SelectedNode = i;
                    end
                end
                
                startTimestamp = app.AdditionalEventInfo.startTimestamp{SelectedNode};

                [app.Mainapp.Data,~,app.RHDAllChannelData,app.ExtractedRHDEventsFlag,Eventstodelete,ExcludedTrialstexttoshow] = Extract_Events_Module_Main_Function(app.Mainapp.Data,app.EventInfo,app.Path,app.Mainapp.Data.Info.RecordingType,app.FileTypeDropDown.Value,app.AnalogThresholdVEditField.Value,InputChannelSelection,app.ExtractedRHDEventsFlag,app.TextArea_2,app.RHDAllChannelData,app.Mainapp.executableFolder,startTimestamp,app.TimearoundEvent,app.AdditionalEventInfo,app.EventsToCombine);
            else
                if strcmp(app.Mainapp.Data.Info.RecordingType,"NEO")
                    startTimestamp = app.NeoEventStartTimeStamp;
                else
                    if isfield(app.Mainapp.Data.Info,'startTimestamp')
                        startTimestamp = app.Mainapp.Data.Info.startTimestamp;
                    end
                end
                
                [app.Mainapp.Data,~,app.RHDAllChannelData,app.ExtractedRHDEventsFlag,Eventstodelete,ExcludedTrialstexttoshow] = Extract_Events_Module_Main_Function(app.Mainapp.Data,app.EventInfo,app.Path,app.Mainapp.Data.Info.RecordingType,app.FileTypeDropDown.Value,app.AnalogThresholdVEditField.Value,InputChannelSelection,app.ExtractedRHDEventsFlag,app.TextArea_2,app.RHDAllChannelData,app.Mainapp.executableFolder,startTimestamp,app.TimearoundEvent,app.AdditionalEventInfo,app.EventsToCombine);
            end
            
            if isfield(app.Mainapp.Data,'Events')
                 if isempty(app.Mainapp.Data.Events)
                    app.Mainapp.Data = rmfield(app.Mainapp.Data, 'Events');
                 end
            end

            if isfield(app.Mainapp.Data,'Events')
                %% Save event related infos for later on the fly extraction
                app.Mainapp.Data = Extract_Events_Module_Add_EventRelatedInfo(app.Mainapp.Data,app.TimeWindowBeforeEventssEditField.Value,app.TimeWindowAfterEventssEditField.Value);
            end

            %% Populate text are
            if ~isempty(app.CostumeChannelIdentityInfo) && isfield(app.Mainapp.Data,'Events')
                if ~isempty(app.Mainapp.Data.Events)
                    [app.Mainapp.Data,Error] = Extract_Events_Module_Costume_Trigger_Identity(app.Mainapp.Data,app.CostumeChannelIdentityInfo);
                    if Error == 0
                        app.TextArea_2.Value{end+1} = strcat('Trigger for event channel where divided in ',num2str(length(app.CostumeChannelIdentityInfo.UniqueIdentities)),' individual channel according to the loaded trigger identity file.');
                        for i = 1:length(app.CostumeChannelIdentityInfo.UniqueIdentities)
                            app.TextArea_2.Value{end+1} = convertStringsToChars(strcat("Channel ",app.CostumeChannelIdentityInfo.UniqueIdentities(i)," contains ",num2str(sum(app.CostumeChannelIdentityInfo.AllIdentities==app.CostumeChannelIdentityInfo.UniqueIdentities(i)))," trigger."));
                        end
                    else
                        app.TextArea_2.Value{end+1} = 'No custom trigger identity could be applied!';
                    end

                end
                app.CostumeChannelIdentityInfo = [];
            end

            %% Show new Info Header entries in main window
            
            Utility_Show_Info_Loaded_Data(app.Mainapp);
            
            [app.Mainapp] = Organize_Set_MainWindow_Dropdown(app.Mainapp,app.Mainapp.Data);
            
            if ~isempty(app.Mainapp.EventChannelDropDown.Items)
                app.Mainapp.EventChannelDropDown.Value = app.Mainapp.EventChannelDropDown.Items{1};
            end
            
            KeepSpikes = 0;
            KeepEvents = 0;
            if isfield(app.Mainapp.Data,'Spikes') && strcmp(app.Mainapp.Plotspikes,"Spikes")
                KeepSpikes = 1;
            end
            if isfield(app.Mainapp.Data,'Events') && strcmp(app.Mainapp.PlotEvents,"Events")
                KeepEvents = 1;
            end
            
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Subsequent","MainWindowTimeManipulation",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
            Organize_Reset_Main_Plot(app.Mainapp,1,KeepEvents,KeepSpikes,0,app.Mainapp.CurrentTimePoints);
            
            % add text about channel combination
            if ~isempty(ExcludedTrialstexttoshow)
                app.TextArea_2.Value = [app.TextArea_2.Value;"";ExcludedTrialstexttoshow];
            end
       
            if ~isempty(app.EventsToCombine)
                texttoshow = {};
                texttoshow{1} = convertStringsToChars(strcat("Combined event channel: ",num2str(app.EventsToCombine.CombinedChannel)," into combined channel with name(s): ",strjoin(string(app.EventsToCombine.NewCombinedChannelNames),", ")));
                texttoshow1 = {};
                texttoshow1{1} = '';
                app.TextArea_2.Value = [texttoshow;texttoshow1;app.TextArea_2.Value];
            end
            
            app.Mainapp = Utility_Change_Light_Dark_Mode(app.Mainapp,"MainWindow");
            
            Organize_Delete_All_Open_Windows(app.Mainapp,0);
            
            app.EventsToCombine = [];

            app = Utility_Change_Light_Dark_Mode(app,"ExtractEvents");
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            try
                if ~isempty(app.ShowChannelPlotWindow)
                    delete(app.ShowChannelPlotWindow);
                end
                if ~isempty(app.CombineEventsWindow)
                    delete(app.CombineEventsWindow);
                end
            catch
            end
            
            try
                app.Mainapp.EventExtractionWindow = [];
            end

            delete(app);
        end

        % Value changed function: TimeWindowBeforeEventssEditField
        function TimeWindowBeforeEventssEditFieldValueChanged(app, event)
            [app.TimeWindowBeforeEventssEditField.Value] = Utility_SimpleCheckInputs(app.TimeWindowBeforeEventssEditField.Value,"One",'0.2',0,0);
            value = app.TimeWindowBeforeEventssEditField.Value;
            app.TimearoundEvent(1) = str2double(value);
        end

        % Value changed function: TimeWindowAfterEventssEditField
        function TimeWindowAfterEventssEditFieldValueChanged(app, event)
            [app.TimeWindowAfterEventssEditField.Value] = Utility_SimpleCheckInputs(app.TimeWindowAfterEventssEditField.Value,"One",'0.5',0,0);
            value = app.TimeWindowAfterEventssEditField.Value;
            app.TimearoundEvent(2) = str2double(value);
        end

        % Value changed function: AnalogThresholdVEditField
        function AnalogThresholdVEditFieldValueChanged(app, event)
            [app.AnalogThresholdVEditField.Value] = Utility_SimpleCheckInputs(app.AnalogThresholdVEditField.Value,"One",'1',0,1);
        end

        % Value changed function: InputChannelSelectionEditField
        function InputChannelSelectionEditFieldValueChanged(app, event)
            commafind = find(app.InputChannelSelectionEditField.Value==',');

            if isempty(commafind)
                SelectedChannel = str2double(app.InputChannelSelectionEditField.Value);
                if isnan(SelectedChannel)
                    msgbox("Wrong format. Enter either single number or comma seperated numbers!")
                    app.InputChannelSelectionEditField.Value = event.PreviousValue;
                    return;
                end
            else
                strArray = strsplit(app.InputChannelSelectionEditField.Value, ',');
                numericVector = str2double(strArray);
                if sum(isnan(numericVector))>0
                    msgbox("Wrong format. Enter either single number or comma seperated numbers!")
                    app.InputChannelSelectionEditField.Value = event.PreviousValue;
                    return;
                else
                    SelectedChannel = numericVector;
                end
            end
            
            if ~strcmp(app.Mainapp.Data.Info.RecordingType,"Spike2")

                commafind = find(app.maxInputSelection==',');
                if isempty(commafind)
                    MaxSelectedChannel = str2double(app.maxInputSelection);
                else
                    strArray = strsplit(app.maxInputSelection, ',');
                    numericVector = str2double(strArray);
                    MaxSelectedChannel = numericVector;
                end

                if ~all(ismember(SelectedChannel,MaxSelectedChannel))
                    msgbox("Channel Selection exceeds max number of found channel! Value is autoadjusted to previous one.")
                    app.InputChannelSelectionEditField.Value = event.PreviousValue;
                    return;

                end

            end
            
            if ~isempty(app.CostumeChannelIdentityInfo)
                if length(strsplit(app.InputChannelSelectionEditField.Value,',')) == 1
                    app.InputChannelSelectionEditField.BackgroundColor = [0, 1, 0];
                else
                    app.InputChannelSelectionEditField.BackgroundColor = [1, 0, 0];
                end
            end
        end

        % Close request function: ExtractTriggerTimesWindowUIFigure
        function ExtractTriggerTimesWindowUIFigureCloseRequest(app, event)
            try
                if ~isempty(app.ShowChannelPlotWindow)
                    delete(app.ShowChannelPlotWindow);
                end
    
                if ~isempty(app.CombineEventsWindow)
                    delete(app.CombineEventsWindow);
                end
            
                app.Mainapp.EventExtractionWindow = [];
            catch

            end

            delete(app);
            
        end

        % Button pushed function: LoadCostumeTriggerIdentityButton
        function LoadCostumeTriggerIdentityButtonPushed(app, event)
            if ~isempty(app.EventsToCombine)
                msgbox("Event channel combination was added before. Currently you can only do either channel combination or custom trigger identity modication at a time, not both.")
                return;
            else
                try
                    if ~isempty(app.CombineEventsWindow)
                        delete(app.CombineEventsWindow);
                    end
                catch
    
                end
            end

            if ~isempty(app.CostumeChannelIdentityInfo)
                app.CostumeChannelIdentityInfo = [];
                app.InputChannelSelectionEditField.BackgroundColor = [1, 1, 1];
            end
            
            CostumeTriggerIdentityWindow = Load_Costume_Trigger_Identity_Window(app.Mainapp, app);
    
            uiwait(CostumeTriggerIdentityWindow.LoadCostumeTriggerIdentityUIFigure);
            % Wait for the app to close
            
            if isvalid(CostumeTriggerIdentityWindow)
                if isempty(CostumeTriggerIdentityWindow.EventInfo)
                    msgbox("No valid file selected.")
                    delete(CostumeTriggerIdentityWindow);
                end
            end

            if isvalid(CostumeTriggerIdentityWindow)
                CostumeTriggerIdentityData = CostumeTriggerIdentityWindow.EventInfo;
                delete(CostumeTriggerIdentityWindow)
            else
                msgbox("Load Custom Trigger Identity Window closed.")
                app.CostumeChannelIdentityInfo = [];
                app = Utility_Change_Light_Dark_Mode(app,"ExtractEvents");
                return
            end
            
            app.CostumeChannelIdentityInfo = CostumeTriggerIdentityData;

            if iscell(app.TextArea_2.Value)
                try
                    TextToShow = cell(size(app.TextArea_2.Value,1),1);

                    for i = 2:length(app.TextArea_2.Value)
                        TextToShow{i}=app.TextArea_2.Value{i};
                    end
                    
                    TextToShow{1} = 'Custom trigger identities where loaded. PLEASE REMEMBER that only a single event channel of the type specified can be loaded! Please adjust the "Input Channel Selection" field accordingly.';
                    
                    app.TextArea_2.Value = TextToShow;                       
                end
            end

            if length(strsplit(app.InputChannelSelectionEditField.Value,',')) > 1
                app.InputChannelSelectionEditField.BackgroundColor = [1, 0, 0];
            end

        end

        % Menu selected function: DeleteEventTriggerIndicesMenu
        function DeleteEventTriggerIndicesMenuSelected(app, event)
                if isfield(app.Mainapp.Data,'Events')
                    EventsRelatedApp.TextArea = [];
                    app.Mainapp.EventIndiceRejectionWindow = Preprocessing_Events_Trial_Rejection_Window(app.Mainapp,EventsRelatedApp,"Event Trigger Extraction");
                else
                    msgbox("Error: No event data found.");
                end
        end

        % Button pushed function: CombineInputChannelButton
        function CombineInputChannelButtonPushed(app, event)
            if ~isempty(app.CostumeChannelIdentityInfo)
                msgbox("Custom trigger identity was added before. Currently you can only do either channel combination or custom trigger identity modication at a time, not both.")
                return;
            end

            app.CombineEventsWindow = Combine_Events(app.Mainapp,app);

        end

        % Value changed function: EventTypeDropDown
        function EventTypeDropDownValueChanged(app, event)
            value = app.EventTypeDropDown.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ExtractTriggerTimesWindowUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {678, 678};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ExtractTriggerTimesWindowUIFigure = uifigure('Visible', 'off');
            app.ExtractTriggerTimesWindowUIFigure.Name = 'Extract Trigger Times Window';
            app.ExtractTriggerTimesWindowUIFigure.Icon = 'Logo.png';
            app.ExtractTriggerTimesWindowUIFigure.CloseRequestFcn = createCallbackFcn(app, @ExtractTriggerTimesWindowUIFigureCloseRequest, true);
            app.ExtractTriggerTimesWindowUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.DeleteEventTriggerIndicesMenu = uimenu(app.ExtractTriggerTimesWindowUIFigure);
            app.DeleteEventTriggerIndicesMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteEventTriggerIndicesMenuSelected, true);
            app.DeleteEventTriggerIndicesMenu.Text = '   Delete Event Trigger Indices   ';
            app.GridLayout = uigridlayout(app.ExtractTriggerTimesWindowUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.ExtractEventRelatedDataPanel = uipanel(app.RightPanel);
            app.ExtractEventRelatedDataPanel.Title = 'Extract Event Related Data';
            app.Panel_2 = uipanel(app.ExtractEventRelatedDataPanel);
            app.TimeWindowBeforeTriggersEditFieldLabel = uilabel(app.Panel_2);
            app.TimeWindowBeforeTriggersEditFieldLabel.Text = {'Time Window '; 'Before Trigger [s]'};
            app.TimeWindowBeforeEventssEditField = uieditfield(app.Panel_2, 'text');
            app.TimeWindowBeforeEventssEditField.ValueChangedFcn = createCallbackFcn(app, @TimeWindowBeforeEventssEditFieldValueChanged, true);
            app.TimeWindowBeforeEventssEditField.Value = '0.2';
            app.TimeWindowAfterTriggersEditFieldLabel = uilabel(app.Panel_2);
            app.TimeWindowAfterTriggersEditFieldLabel.Text = {'Time Window '; 'After Trigger [s]'};
            app.TimeWindowAfterEventssEditField = uieditfield(app.Panel_2, 'text');
            app.TimeWindowAfterEventssEditField.ValueChangedFcn = createCallbackFcn(app, @TimeWindowAfterEventssEditFieldValueChanged, true);
            app.TimeWindowAfterEventssEditField.Value = '0.7';
            app.TextArea = uitextarea(app.RightPanel);
            app.TextArea_2 = uitextarea(app.RightPanel);
            app.InfoandFilesinPathLabel = uilabel(app.RightPanel);
            app.InfoandFilesinPathLabel.Text = 'Info and Files in Path';
            app.FilePathLabel = uilabel(app.RightPanel);
            app.FilePathLabel.Text = 'File Path';
            app.EventExtractionSettingsPanel = uipanel(app.RightPanel);
            app.EventExtractionSettingsPanel.Title = 'Event Extraction Settings';
            app.Panel = uipanel(app.EventExtractionSettingsPanel);
            app.FileTypeDropDown = uidropdown(app.Panel);
            app.FileTypeDropDown.Items = {'Digital Inputs', 'Analog Input', 'AUX Inputs', 'DIN Inputs'};
            app.FileTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @FileTypeDropDownValueChanged, true);
            app.FileTypeDropDown.Value = 'Digital Inputs';
            app.SelectEventChannelTypeLabel = uilabel(app.Panel);
            app.SelectEventChannelTypeLabel.Text = 'Select Event Channel Type';
            app.CombineInputChannelButton = uibutton(app.Panel, 'push');
            app.CombineInputChannelButton.ButtonPushedFcn = createCallbackFcn(app, @CombineInputChannelButtonPushed, true);
            app.CombineInputChannelButton.Text = 'Combine Input Channel';
            app.InputChannelSelectionEditField = uieditfield(app.Panel, 'text');
            app.InputChannelSelectionEditField.ValueChangedFcn = createCallbackFcn(app, @InputChannelSelectionEditFieldValueChanged, true);
            app.AnalogThresholdVEditField = uieditfield(app.Panel, 'text');
            app.AnalogThresholdVEditField.ValueChangedFcn = createCallbackFcn(app, @AnalogThresholdVEditFieldValueChanged, true);
            app.AnalogThresholdVEditField.Value = '1';
            app.TriggerTresholdVLabel = uilabel(app.Panel);
            app.TriggerTresholdVLabel.Text = 'Trigger Treshold [V]';
            app.InputChannelSelectionLabel = uilabel(app.Panel);
            app.InputChannelSelectionLabel.Text = {'Input Channel'; 'Selection'};
            app.TriggerTypeDropDownLabel = uilabel(app.Panel);
            app.TriggerTypeDropDownLabel.Text = 'Trigger Type';
            app.EventTypeDropDown = uidropdown(app.Panel);
            app.EventTypeDropDown.Items = {'Rising Edge', 'Falling Edge'};
            app.EventTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @EventTypeDropDownValueChanged, true);
            app.EventTypeDropDown.Value = 'Rising Edge';
            app.RecordingSystemLabel = uilabel(app.RightPanel);
            app.RecordingSystemLabel.Enable = 'off';
            app.RecordingSystemLabel.Visible = 'off';
            app.RecordingSystemLabel.Text = 'Recording System';
            app.RecordingSystem = uieditfield(app.RightPanel, 'text');
            app.RecordingSystem.Editable = 'off';
            app.RecordingSystem.Enable = 'off';
            app.RecordingSystem.Visible = 'off';
            app.NrInputChannelfoundinFolderLabel = uilabel(app.RightPanel);
            app.NrInputChannelfoundinFolderLabel.Enable = 'off';
            app.NrInputChannelfoundinFolderLabel.Visible = 'off';
            app.NrInputChannelfoundinFolderLabel.Text = {'Nr. Input Channel'; 'found in Folder'};
            app.NrInputChinfolderEditField = uieditfield(app.RightPanel, 'text');
            app.NrInputChinfolderEditField.Editable = 'off';
            app.NrInputChinfolderEditField.Enable = 'off';
            app.NrInputChinfolderEditField.Visible = 'off';
            app.EventRelatedTrialsDataSettingsLabel = uilabel(app.RightPanel);
            app.EventRelatedTrialsDataSettingsLabel.Text = 'Event Related (Trials) Data Settings';
            app.SetFoldermanuallyButton = uibutton(app.RightPanel, 'push');
            app.SetFoldermanuallyButton.ButtonPushedFcn = createCallbackFcn(app, @SetFoldermanuallyButtonPushed, true);
            app.SetFoldermanuallyButton.Text = {'Set Folder '; 'Manually'};
            app.StartEventExtractionButton = uibutton(app.RightPanel, 'push');
            app.StartEventExtractionButton.ButtonPushedFcn = createCallbackFcn(app, @StartEventExtractionButtonPushed, true);
            app.StartEventExtractionButton.Text = {'Start Event '; 'Extraction'};
            app.PlotInputChannelButton = uibutton(app.RightPanel, 'push');
            app.PlotInputChannelButton.ButtonPushedFcn = createCallbackFcn(app, @PlotInputChannelButtonPushed, true);
            app.PlotInputChannelButton.Text = {'Plot Input '; 'Channel'};
            app.LoadCostumeTriggerIdentityButton = uibutton(app.RightPanel, 'push');
            app.LoadCostumeTriggerIdentityButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCostumeTriggerIdentityButtonPushed, true);
            app.LoadCostumeTriggerIdentityButton.Text = {'Load Custom Trigger'; 'Identity'};
            app.TriggerTimeExtractionSettingsLabel = uilabel(app.RightPanel);
            app.TriggerTimeExtractionSettingsLabel.Text = 'Trigger Time Extraction Settings';
            app.CloseButton = uibutton(app.ExtractTriggerTimesWindowUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.ExtractTriggerTimesWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Extract_Trigger_Times(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ExtractTriggerTimesWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ExtractTriggerTimesWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ExtractTriggerTimesWindowUIFigure)
        end
    end
end