classdef FieldTrip_Event_Analysis_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FieldTripEventAnalysisUIFigure  matlab.ui.Figure
        TFMethodDropDown                matlab.ui.control.DropDown
        TFMethodDropDownLabel           matlab.ui.control.Label
        TimeFrequencyPowerandConnectivityAnalysisLabel  matlab.ui.control.Label
        EventNumberSelectionEditField_5  matlab.ui.control.EditField
        TriggerNumberSelectionMatlabExpressionsLabel_4  matlab.ui.control.Label
        EventNumberSelectionEditField_2  matlab.ui.control.EditField
        TriggerNumberSelectionMatlabExpressionsLabel_2  matlab.ui.control.Label
        ExportButton                    matlab.ui.control.Button
        CurrentlyAvailableVariablestoExportTextArea  matlab.ui.control.TextArea
        CurrentlyAvailableVariablestoExportTextAreaLabel  matlab.ui.control.Label
        TimeFrequencyPowerandConnectivityAnalysisPanel  matlab.ui.container.Panel
        SlidingWindowLengthsLabel       matlab.ui.control.Label
        EventNumberSelectionEditField_4  matlab.ui.control.EditField
        EventNumberSelectionEditField_6  matlab.ui.control.EditField
        TriggerNumberSelectionMatlabExpressionsLabel_5  matlab.ui.control.Label
        ConnectivityButton              matlab.ui.control.Button
        BaselineCorrectCheckBox         matlab.ui.control.CheckBox
        MultipleChannelTimeFrequencyPowerButton  matlab.ui.control.Button
        SingleChannelTimeFrequencyPowerButton  matlab.ui.control.Button
        EventRelatedPotentialLabel      matlab.ui.control.Label
        CloseButton                     matlab.ui.control.Button
        EventRelatedPotentialPanel      matlab.ui.container.Panel
        BaselineCorrectCheckBox_2       matlab.ui.control.CheckBox
        MultipleChannelERPPlotButton    matlab.ui.control.Button
        SingleChannelERPPlotButton      matlab.ui.control.Button
        AnalysisParameterPanel          matlab.ui.container.Panel
        AnalysisParameterLabel          matlab.ui.control.Label
        EventNumberSelectionEditField_3  matlab.ui.control.EditField
        TriggerNumberSelectionMatlabExpressionsLabel_3  matlab.ui.control.Label
        DataToExtractFromDropDown       matlab.ui.control.DropDown
        DataToExtractFromDropDownLabel  matlab.ui.control.Label
        EventChannelSelectionDropDown   matlab.ui.control.DropDown
        EventChannelSelectionDropDownLabel  matlab.ui.control.Label
        ChannelSelectionDropDown        matlab.ui.control.DropDown
        ChannelSelectionDropDownLabel   matlab.ui.control.Label
        DataTypeDropDown                matlab.ui.control.DropDown
        EventDataTypeDropDownLabel      matlab.ui.control.Label
        EventNumberSelectionEditField   matlab.ui.control.EditField
        TriggerNumberSelectionMatlabExpressionsLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        Mainapp = []; % Description
        TimearoundEvent = [];
        Info = [];
        CurrentDataShown = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            movegui(app.FieldTripEventAnalysisUIFigure, 'center');

            app.Mainapp = Mainapp;
            
            app = Utility_Change_Light_Dark_Mode(app,"FieldTripEventWindow");
            
            TimeRange = str2double(strsplit(app.Mainapp.Data.Info.EventRelatedDataTimeRange,' '));
            app.EventNumberSelectionEditField_3.Value = strcat(num2str(-TimeRange(1)),',0');

            if isfield(app.Mainapp.Data,'Preprocessed')
                app.DataToExtractFromDropDown.Items{1} = 'Preprocessed Data';
                app.DataToExtractFromDropDown.Items{2} = 'Raw Data';
            else
                app.DataToExtractFromDropDown.Items{1} = 'Raw Data';
            end
            
            for i = 1:length(app.Mainapp.Data.Info.EventChannelNames)
                app.EventChannelSelectionDropDown.Items{i} = convertStringsToChars(app.Mainapp.Data.Info.EventChannelNames{i});
            end
            
            value = app.TFMethodDropDown.Value;
            if strcmp(value,"mtmconvol")
                app.EventNumberSelectionEditField_4.Value = '2';
            else
                app.EventNumberSelectionEditField_4.Value = '3';
            end
            value = app.TFMethodDropDown.Value;
            if strcmp(value,"mtmconvol")
                app.EventNumberSelectionEditField_4.Value = '2';
                app.SlidingWindowLengthsLabel.Text = "Sliding Window Length (s)";
            else
                app.EventNumberSelectionEditField_4.Value = '3';
                app.SlidingWindowLengthsLabel.Text = "Wavelet Width";
            end

            %% -------------------- Check if prepro event related data found and can be offered -------------------- 
            [texttoshow,Value] = Event_Module_Check_Event_Preprocessing(app.Mainapp.Data,app.EventChannelSelectionDropDown.Value,"Raw Event Related Data");
            app.DataTypeDropDown.Items = {};
            app.DataTypeDropDown.Items = texttoshow;
            app.DataTypeDropDown.Value = Value;

            %% -------------------- Set up time -------------------- 
            spaceindicie = strfind(app.Mainapp.Data.Info.EventRelatedDataTimeRange," ");
            app.TimearoundEvent(1) = str2double(app.Mainapp.Data.Info.EventRelatedDataTimeRange(1:spaceindicie(1)-1));
            app.TimearoundEvent(2) = str2double(app.Mainapp.Data.Info.EventRelatedDataTimeRange(spaceindicie(1)+1:end));

            %% -------------------- Set UP Channel Selection -------------------- 

            AllChannels = {};
            if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 1
                TempActiveChannel = (str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)*str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)+1)-sort(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
                for i = 1:size(app.Mainapp.Data.Raw,1)  
                    AllChannels{i} = num2str(TempActiveChannel(i));
                end
            else
                for i = 1:size(app.Mainapp.Data.Raw,1)
                    AllChannels{i} = num2str(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel(i));
                end
            end

            app.ChannelSelectionDropDown.Items = AllChannels;
            
            %% -------------------- Extract Event Related Data -------------------- 
            [app.Mainapp.Data,~] = Event_Module_Extract_Event_Related_Data(app.Mainapp.Data,app.EventChannelSelectionDropDown.Value,app.TimearoundEvent,app.DataToExtractFromDropDown.Value,app.DataTypeDropDown.Value);


            %% -------------------- Populate event channel selection -------------------- 
            [app.EventNumberSelectionEditField.Value] = Event_Module_Check_EventInput(app.EventNumberSelectionEditField.Value,app.Mainapp.Data,app.EventChannelSelectionDropDown.Value,app.DataTypeDropDown.Value,1);
            

            app.Info.TimeFrequenyPowerFreqs = app.EventNumberSelectionEditField_2.Value;
            app.Info.SlidingWindowLength = app.EventNumberSelectionEditField_4.Value;
            app.Info.BaseLineWindow = app.EventNumberSelectionEditField_3.Value;
            app.Info.BaselineNormalize = app.BaselineCorrectCheckBox.Value;
            app.Info.TFMethod = app.TFMethodDropDown.Value;
        end

        % Button pushed function: SingleChannelERPPlotButton
        function SingleChannelERPPlotButtonPushed(app, event)
            app.Info.TimeFrequenyPowerFreqs = app.EventNumberSelectionEditField_2.Value;
            app.Info.SlidingWindowLength = app.EventNumberSelectionEditField_4.Value;
            app.Info.BaseLineWindow = app.EventNumberSelectionEditField_3.Value;
            app.Info.BaselineNormalize = app.BaselineCorrectCheckBox.Value;
            app.Info.BaselineNormalizeERP = app.BaselineCorrectCheckBox_2.Value;
            app.Info.TFMethod = app.TFMethodDropDown.Value;
            app.Info.Trial = app.EventNumberSelectionEditField.Value;
            app.Info.TimeStepLength = app.EventNumberSelectionEditField_5.Value;
            app.Info.ChannelToCompare = app.EventNumberSelectionEditField_6.Value;
            
            app.CurrentDataShown = [];

            app.CurrentDataShown = Event_Module_FieldTrip_Event_Analysis(app.Mainapp.Data,app.DataToExtractFromDropDown.Value,"SingleERP",app.ChannelSelectionDropDown.Value,app.EventChannelSelectionDropDown.Value,app.TimearoundEvent,app.Info,app.DataTypeDropDown.Value,app.EventNumberSelectionEditField.Value);

            if ~isempty(app.CurrentDataShown)
                fields = fieldnames(app.CurrentDataShown);
                if length(fields)==1
                    % Get top-level fields
                    mainFields = fieldnames(app.CurrentDataShown);
                    
                    % For now, assume only one main field
                    mainField = mainFields{1};
                    
                    % Get subfields of that main field
                    subFields = fieldnames(app.CurrentDataShown.(mainField));
                    
                    % Prepare a cell array of strings for the TextArea
                    outputText = {};
                    outputText{end+1} = sprintf('%s:', mainField); % top-level field name
                    
                    for i = 1:numel(subFields)
                        subFieldName = subFields{i};
                        value = app.CurrentDataShown.(mainField).(subFieldName);
                    
                        % Get size as a string
                        dims = size(value);
                        dimStr = sprintf('%dx', dims);
                        dimStr(end) = []; % remove last 'x'
                    
                        % Add line
                        outputText{end+1} = sprintf('   %s: %s', subFieldName, dimStr);
                    end
                    
                    % Assign to TextArea
                    app.CurrentlyAvailableVariablestoExportTextArea.Value = outputText;
                else

                end
            end

        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            try
                app.Mainapp.FieldTripLFPWindow = [];
            catch
                
            end
            
            delete(app)
        end

        % Button pushed function: MultipleChannelERPPlotButton
        function MultipleChannelERPPlotButtonPushed(app, event)
            app.Info.TimeFrequenyPowerFreqs = app.EventNumberSelectionEditField_2.Value;
            app.Info.SlidingWindowLength = app.EventNumberSelectionEditField_4.Value;
            app.Info.BaseLineWindow = app.EventNumberSelectionEditField_3.Value;
            app.Info.BaselineNormalize = app.BaselineCorrectCheckBox.Value;
            app.Info.BaselineNormalizeERP = app.BaselineCorrectCheckBox_2.Value;
            app.Info.TFMethod = app.TFMethodDropDown.Value;
            app.Info.Trial = app.EventNumberSelectionEditField.Value;
            app.Info.TimeStepLength = app.EventNumberSelectionEditField_5.Value;
            app.Info.ChannelToCompare = app.EventNumberSelectionEditField_6.Value;
            
            app.CurrentDataShown = [];

            app.CurrentDataShown = Event_Module_FieldTrip_Event_Analysis(app.Mainapp.Data,app.DataToExtractFromDropDown.Value,"MultipleERP",app.ChannelSelectionDropDown.Value,app.EventChannelSelectionDropDown.Value,app.TimearoundEvent,app.Info,app.DataTypeDropDown.Value,app.EventNumberSelectionEditField.Value);
            
            if ~isempty(app.CurrentDataShown)
                fields = fieldnames(app.CurrentDataShown);
                if length(fields)==1
                    % Get top-level fields
                    mainFields = fieldnames(app.CurrentDataShown);
                    
                    % For now, assume only one main field
                    mainField = mainFields{1};
                    
                    % Get subfields of that main field
                    subFields = fieldnames(app.CurrentDataShown.(mainField));
                    
                    % Prepare a cell array of strings for the TextArea
                    outputText = {};
                    outputText{end+1} = sprintf('%s:', mainField); % top-level field name
                    
                    for i = 1:numel(subFields)
                        subFieldName = subFields{i};
                        value = app.CurrentDataShown.(mainField).(subFieldName);
                    
                        % Get size as a string
                        dims = size(value);
                        dimStr = sprintf('%dx', dims);
                        dimStr(end) = []; % remove last 'x'
                    
                        % Add line
                        outputText{end+1} = sprintf('   %s: %s', subFieldName, dimStr);
                    end
                    
                    % Assign to TextArea
                    app.CurrentlyAvailableVariablestoExportTextArea.Value = outputText;
                else

                end
            end

        end

        % Button pushed function: SingleChannelTimeFrequencyPowerButton
        function SingleChannelTimeFrequencyPowerButtonPushed(app, event)
            app.Info.TimeFrequenyPowerFreqs = app.EventNumberSelectionEditField_2.Value;
            app.Info.SlidingWindowLength = app.EventNumberSelectionEditField_4.Value;
            app.Info.BaseLineWindow = app.EventNumberSelectionEditField_3.Value;
            app.Info.BaselineNormalize = app.BaselineCorrectCheckBox.Value;
            app.Info.BaselineNormalizeERP = app.BaselineCorrectCheckBox_2.Value;
            app.Info.TFMethod = app.TFMethodDropDown.Value;
            app.Info.Trial = app.EventNumberSelectionEditField.Value;
            app.Info.TimeStepLength = app.EventNumberSelectionEditField_5.Value;
            app.Info.ChannelToCompare = app.EventNumberSelectionEditField_6.Value;
            
            app.CurrentDataShown = [];

            app.CurrentDataShown = Event_Module_FieldTrip_Event_Analysis(app.Mainapp.Data,app.DataToExtractFromDropDown.Value,"SingleChannelTimeFrequencyPower",app.ChannelSelectionDropDown.Value,app.EventChannelSelectionDropDown.Value,app.TimearoundEvent,app.Info,app.DataTypeDropDown.Value,app.EventNumberSelectionEditField.Value);

            if ~isempty(app.CurrentDataShown)
                fields = fieldnames(app.CurrentDataShown);
                if length(fields)==1
                    % Get top-level fields
                    mainFields = fieldnames(app.CurrentDataShown);
                    
                    % For now, assume only one main field
                    mainField = mainFields{1};
                    
                    % Get subfields of that main field
                    subFields = fieldnames(app.CurrentDataShown.(mainField));
                    
                    % Prepare a cell array of strings for the TextArea
                    outputText = {};
                    outputText{end+1} = sprintf('%s:', mainField); % top-level field name
                    
                    for i = 1:numel(subFields)
                        subFieldName = subFields{i};
                        value = app.CurrentDataShown.(mainField).(subFieldName);
                    
                        % Get size as a string
                        dims = size(value);
                        dimStr = sprintf('%dx', dims);
                        dimStr(end) = []; % remove last 'x'
                    
                        % Add line
                        outputText{end+1} = sprintf('   %s: %s', subFieldName, dimStr);
                    end
                    
                    % Assign to TextArea
                    app.CurrentlyAvailableVariablestoExportTextArea.Value = outputText;
                else

                end
            end
        end

        % Value changed function: TFMethodDropDown
        function TFMethodDropDownValueChanged(app, event)
            value = app.TFMethodDropDown.Value;
            if strcmp(value,"mtmconvol")
                app.EventNumberSelectionEditField_4.Value = '2';
                app.SlidingWindowLengthsLabel.Text = "Sliding Window Length (s)";
            else
                app.EventNumberSelectionEditField_4.Value = '3';
                app.SlidingWindowLengthsLabel.Text = "Wavelet Width";
            end
        end

        % Button pushed function: ConnectivityButton
        function ConnectivityButtonPushed(app, event)
            app.Info.TimeFrequenyPowerFreqs = app.EventNumberSelectionEditField_2.Value;
            app.Info.SlidingWindowLength = app.EventNumberSelectionEditField_4.Value;
            app.Info.BaseLineWindow = app.EventNumberSelectionEditField_3.Value;
            app.Info.BaselineNormalize = app.BaselineCorrectCheckBox.Value;
            app.Info.BaselineNormalizeERP = app.BaselineCorrectCheckBox_2.Value;
            app.Info.TFMethod = app.TFMethodDropDown.Value;
            app.Info.Trial = app.EventNumberSelectionEditField.Value;
            app.Info.TimeStepLength = app.EventNumberSelectionEditField_5.Value;
            app.Info.ChannelToCompare = app.EventNumberSelectionEditField_6.Value;
            
            app.CurrentDataShown = [];

            app.CurrentDataShown = Event_Module_FieldTrip_Event_Analysis(app.Mainapp.Data,app.DataToExtractFromDropDown.Value,"ConnectivityAnalysis",app.ChannelSelectionDropDown.Value,app.EventChannelSelectionDropDown.Value,app.TimearoundEvent,app.Info,app.DataTypeDropDown.Value,app.EventNumberSelectionEditField.Value);

            if ~isempty(app.CurrentDataShown)
                fields = fieldnames(app.CurrentDataShown);
                if length(fields)==1
                    % Get top-level fields
                    mainFields = fieldnames(app.CurrentDataShown);
                    
                    % For now, assume only one main field
                    mainField = mainFields{1};
                    
                    % Get subfields of that main field
                    subFields = fieldnames(app.CurrentDataShown.(mainField));
                    
                    % Prepare a cell array of strings for the TextArea
                    outputText = {};
                    outputText{end+1} = sprintf('%s:', mainField); % top-level field name
                    
                    for i = 1:numel(subFields)
                        subFieldName = subFields{i};
                        value = app.CurrentDataShown.(mainField).(subFieldName);
                    
                        % Get size as a string
                        dims = size(value);
                        dimStr = sprintf('%dx', dims);
                        dimStr(end) = []; % remove last 'x'
                    
                        % Add line
                        outputText{end+1} = sprintf('   %s: %s', subFieldName, dimStr);
                    end
                    
                    % Assign to TextArea
                    app.CurrentlyAvailableVariablestoExportTextArea.Value = outputText;
                else
                    % Get top-level fields
                    mainFields = fieldnames(app.CurrentDataShown);
                    
                    % For now, assume only one main field
                    mainField = mainFields{1};
                    
                    % Get subfields of that main field
                    subFields = fieldnames(app.CurrentDataShown.(mainField));
                    
                    % Prepare a cell array of strings for the TextArea
                    outputText = {};
                    outputText{end+1} = sprintf('%s:', mainField); % top-level field name
                    
                    for i = 1:numel(subFields)
                        subFieldName = subFields{i};
                        value = app.CurrentDataShown.(mainField).(subFieldName);
                    
                        % Get size as a string
                        dims = size(value);
                        dimStr = sprintf('%dx', dims);
                        dimStr(end) = []; % remove last 'x'
                    
                        % Add line
                        outputText{end+1} = sprintf('   %s: %s', subFieldName, dimStr);
                    end
                    
                    % Assign to TextArea
                    app.CurrentlyAvailableVariablestoExportTextArea.Value = outputText;
                end
            end

        end

        % Close request function: FieldTripEventAnalysisUIFigure
        function FieldTripEventAnalysisUIFigureCloseRequest(app, event)
            try
                app.Mainapp.FieldTripLFPWindow = [];
            catch
                
            end
            
            delete(app)
        end

        % Button pushed function: MultipleChannelTimeFrequencyPowerButton
        function MultipleChannelTimeFrequencyPowerButtonPushed(app, event)
            app.Info.TimeFrequenyPowerFreqs = app.EventNumberSelectionEditField_2.Value;
            app.Info.SlidingWindowLength = app.EventNumberSelectionEditField_4.Value;
            app.Info.BaseLineWindow = app.EventNumberSelectionEditField_3.Value;
            app.Info.BaselineNormalize = app.BaselineCorrectCheckBox.Value;
            app.Info.BaselineNormalizeERP = app.BaselineCorrectCheckBox_2.Value;
            app.Info.TFMethod = app.TFMethodDropDown.Value;
            app.Info.Trial = app.EventNumberSelectionEditField.Value;
            app.Info.TimeStepLength = app.EventNumberSelectionEditField_5.Value;
            app.Info.ChannelToCompare = app.EventNumberSelectionEditField_6.Value;
            
            app.CurrentDataShown = [];

            app.CurrentDataShown = Event_Module_FieldTrip_Event_Analysis(app.Mainapp.Data,app.DataToExtractFromDropDown.Value,"AllChannelTimeFrequencyPower",app.ChannelSelectionDropDown.Value,app.EventChannelSelectionDropDown.Value,app.TimearoundEvent,app.Info,app.DataTypeDropDown.Value,app.EventNumberSelectionEditField.Value);

            if ~isempty(app.CurrentDataShown)
                fields = fieldnames(app.CurrentDataShown);
                if length(fields)==1
                    % Get top-level fields
                    mainFields = fieldnames(app.CurrentDataShown);
                    
                    % For now, assume only one main field
                    mainField = mainFields{1};
                    
                    % Get subfields of that main field
                    subFields = fieldnames(app.CurrentDataShown.(mainField));
                    
                    % Prepare a cell array of strings for the TextArea
                    outputText = {};
                    outputText{end+1} = sprintf('%s:', mainField); % top-level field name
                    
                    for i = 1:numel(subFields)
                        subFieldName = subFields{i};
                        value = app.CurrentDataShown.(mainField).(subFieldName);
                    
                        % Get size as a string
                        dims = size(value);
                        dimStr = sprintf('%dx', dims);
                        dimStr(end) = []; % remove last 'x'
                    
                        % Add line
                        outputText{end+1} = sprintf('   %s: %s', subFieldName, dimStr);
                    end
                    
                    % Assign to TextArea
                    app.CurrentlyAvailableVariablestoExportTextArea.Value = outputText;
                else

                end
            end
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            if isempty(app.CurrentDataShown)
                msgbox("No data to export found! First conduct one of the available analysis options!");
                return
            end

            %% ----------------- Get Save Location -----------------
            [file, path] = uiputfile('*.mat', 'Save as');
            
            if ~ischar(file) || ~ischar(path)
                disp("No folder selected.")
                return;
            else
                SavePath = fullfile(path,file);
            end
            
            FieldTripResult = app.CurrentDataShown;

            save(SavePath,'FieldTripResult','-v7.3');

            msgbox(strcat("Data succesfully exported to ",SavePath));
        end

        % Value changed function: EventChannelSelectionDropDown
        function EventChannelSelectionDropDownValueChanged(app, event)

            %% -------------------- Extract Event Related Data -------------------- 
            [app.Mainapp.Data,~] = Event_Module_Extract_Event_Related_Data(app.Mainapp.Data,app.EventChannelSelectionDropDown.Value,app.TimearoundEvent,app.DataToExtractFromDropDown.Value,app.DataTypeDropDown.Value);
            
            %% -------------------- Check if prepro event related data found and can be offered -------------------- 
            [texttoshow,Value] = Event_Module_Check_Event_Preprocessing(app.Mainapp.Data,app.EventChannelSelectionDropDown.Value,app.DataTypeDropDown.Value);
            app.DataTypeDropDown.Items = {};
            app.DataTypeDropDown.Items = texttoshow;
            app.DataTypeDropDown.Value = Value;
            %% -------------------- Populate event channel selection -------------------- 
            [app.EventNumberSelectionEditField.Value] = Event_Module_Check_EventInput(app.EventNumberSelectionEditField.Value,app.Mainapp.Data,app.EventChannelSelectionDropDown.Value,app.DataTypeDropDown.Value,1);
            
        end

        % Value changed function: DataTypeDropDown
        function DataTypeDropDownValueChanged(app, event)

            %% -------------------- Extract Event Related Data -------------------- 
            [app.Mainapp.Data,~] = Event_Module_Extract_Event_Related_Data(app.Mainapp.Data,app.EventChannelSelectionDropDown.Value,app.TimearoundEvent,app.DataToExtractFromDropDown.Value,app.DataTypeDropDown.Value);
            
            %% -------------------- Populate event channel selection -------------------- 
            [app.EventNumberSelectionEditField.Value] = Event_Module_Check_EventInput(app.EventNumberSelectionEditField.Value,app.Mainapp.Data,app.EventChannelSelectionDropDown.Value,app.DataTypeDropDown.Value,1);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FieldTripEventAnalysisUIFigure = uifigure('Visible', 'off');
            app.FieldTripEventAnalysisUIFigure.Name = 'FieldTrip Event Analysis';
            app.FieldTripEventAnalysisUIFigure.Icon = 'Logo.png';
            app.FieldTripEventAnalysisUIFigure.CloseRequestFcn = createCallbackFcn(app, @FieldTripEventAnalysisUIFigureCloseRequest, true);
            app.AnalysisParameterPanel = uipanel(app.FieldTripEventAnalysisUIFigure);
            app.AnalysisParameterPanel.Title = 'Analysis Parameter';
            app.TriggerNumberSelectionMatlabExpressionsLabel = uilabel(app.AnalysisParameterPanel);
            app.TriggerNumberSelectionMatlabExpressionsLabel.Text = {'Trial Selection'; '(Matlab Expressions)'};
            app.EventNumberSelectionEditField = uieditfield(app.AnalysisParameterPanel, 'text');
            app.EventDataTypeDropDownLabel = uilabel(app.AnalysisParameterPanel);
            app.EventDataTypeDropDownLabel.Text = 'Event Data Type';
            app.DataTypeDropDown = uidropdown(app.AnalysisParameterPanel);
            app.DataTypeDropDown.Items = {};
            app.DataTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DataTypeDropDownValueChanged, true);
            app.DataTypeDropDown.Value = {};
            app.ChannelSelectionDropDownLabel = uilabel(app.AnalysisParameterPanel);
            app.ChannelSelectionDropDownLabel.Text = 'Channel Selection';
            app.ChannelSelectionDropDown = uidropdown(app.AnalysisParameterPanel);
            app.ChannelSelectionDropDown.Items = {};
            app.ChannelSelectionDropDown.Value = {};
            app.EventChannelSelectionDropDownLabel = uilabel(app.AnalysisParameterPanel);
            app.EventChannelSelectionDropDownLabel.Text = 'Event Channel Selection';
            app.EventChannelSelectionDropDown = uidropdown(app.AnalysisParameterPanel);
            app.EventChannelSelectionDropDown.Items = {};
            app.EventChannelSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @EventChannelSelectionDropDownValueChanged, true);
            app.EventChannelSelectionDropDown.Value = {};
            app.DataToExtractFromDropDownLabel = uilabel(app.AnalysisParameterPanel);
            app.DataToExtractFromDropDownLabel.Text = 'Data To Extract From';
            app.DataToExtractFromDropDown = uidropdown(app.AnalysisParameterPanel);
            app.DataToExtractFromDropDown.Items = {};
            app.DataToExtractFromDropDown.Value = {};
            app.TriggerNumberSelectionMatlabExpressionsLabel_3 = uilabel(app.AnalysisParameterPanel);
            app.TriggerNumberSelectionMatlabExpressionsLabel_3.Text = {'Baseline Window '; '(from, to) in s'};
            app.EventNumberSelectionEditField_3 = uieditfield(app.AnalysisParameterPanel, 'text');
            app.AnalysisParameterLabel = uilabel(app.AnalysisParameterPanel);
            app.AnalysisParameterLabel.Text = 'Analysis Parameter';
            app.EventRelatedPotentialPanel = uipanel(app.FieldTripEventAnalysisUIFigure);
            app.EventRelatedPotentialPanel.Title = 'Event Related Potential';
            app.SingleChannelERPPlotButton = uibutton(app.EventRelatedPotentialPanel, 'push');
            app.SingleChannelERPPlotButton.ButtonPushedFcn = createCallbackFcn(app, @SingleChannelERPPlotButtonPushed, true);
            app.SingleChannelERPPlotButton.Text = {'Single Channel '; 'ERP Plot'};
            app.MultipleChannelERPPlotButton = uibutton(app.EventRelatedPotentialPanel, 'push');
            app.MultipleChannelERPPlotButton.ButtonPushedFcn = createCallbackFcn(app, @MultipleChannelERPPlotButtonPushed, true);
            app.MultipleChannelERPPlotButton.Text = {'Multiple Channel'; 'ERP Plot'};
            app.BaselineCorrectCheckBox_2 = uicheckbox(app.EventRelatedPotentialPanel);
            app.BaselineCorrectCheckBox_2.Text = {'   Baseline '; '   Correct'};
            app.BaselineCorrectCheckBox_2.Value = true;
            app.CloseButton = uibutton(app.FieldTripEventAnalysisUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.EventRelatedPotentialLabel = uilabel(app.FieldTripEventAnalysisUIFigure);
            app.EventRelatedPotentialLabel.Text = 'Event Related Potential';
            app.TimeFrequencyPowerandConnectivityAnalysisPanel = uipanel(app.FieldTripEventAnalysisUIFigure);
            app.TimeFrequencyPowerandConnectivityAnalysisPanel.Title = 'Time Frequency Power and Connectivity Analysis';
            app.SingleChannelTimeFrequencyPowerButton = uibutton(app.TimeFrequencyPowerandConnectivityAnalysisPanel, 'push');
            app.SingleChannelTimeFrequencyPowerButton.ButtonPushedFcn = createCallbackFcn(app, @SingleChannelTimeFrequencyPowerButtonPushed, true);
            app.SingleChannelTimeFrequencyPowerButton.Text = {'Single Channel'; 'Time Frequency'; 'Power'};
            app.MultipleChannelTimeFrequencyPowerButton = uibutton(app.TimeFrequencyPowerandConnectivityAnalysisPanel, 'push');
            app.MultipleChannelTimeFrequencyPowerButton.ButtonPushedFcn = createCallbackFcn(app, @MultipleChannelTimeFrequencyPowerButtonPushed, true);
            app.MultipleChannelTimeFrequencyPowerButton.Text = {'Multiple Channel'; 'Time Frequency'; 'Power'};
            app.BaselineCorrectCheckBox = uicheckbox(app.TimeFrequencyPowerandConnectivityAnalysisPanel);
            app.BaselineCorrectCheckBox.Text = {'   Baseline '; '   Correct'};
            app.BaselineCorrectCheckBox.Value = true;
            app.ConnectivityButton = uibutton(app.TimeFrequencyPowerandConnectivityAnalysisPanel, 'push');
            app.ConnectivityButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectivityButtonPushed, true);
            app.ConnectivityButton.Text = 'Connectivity';
            app.TriggerNumberSelectionMatlabExpressionsLabel_5 = uilabel(app.TimeFrequencyPowerandConnectivityAnalysisPanel);
            app.TriggerNumberSelectionMatlabExpressionsLabel_5.Text = {'Channel To Compare'; '(Matlab Expressions)'};
            app.EventNumberSelectionEditField_6 = uieditfield(app.TimeFrequencyPowerandConnectivityAnalysisPanel, 'text');
            app.EventNumberSelectionEditField_6.Value = '1:2';
            app.EventNumberSelectionEditField_4 = uieditfield(app.TimeFrequencyPowerandConnectivityAnalysisPanel, 'text');
            app.EventNumberSelectionEditField_4.Value = '5';
            app.SlidingWindowLengthsLabel = uilabel(app.TimeFrequencyPowerandConnectivityAnalysisPanel);
            app.SlidingWindowLengthsLabel.Text = {'Sliding Window'; 'Length (s)'};
            app.CurrentlyAvailableVariablestoExportTextAreaLabel = uilabel(app.FieldTripEventAnalysisUIFigure);
            app.CurrentlyAvailableVariablestoExportTextAreaLabel.Text = {'Currently Available'; 'Variable(s) to Export'};
            app.CurrentlyAvailableVariablestoExportTextArea = uitextarea(app.FieldTripEventAnalysisUIFigure);
            app.ExportButton = uibutton(app.FieldTripEventAnalysisUIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.TriggerNumberSelectionMatlabExpressionsLabel_2 = uilabel(app.FieldTripEventAnalysisUIFigure);
            app.TriggerNumberSelectionMatlabExpressionsLabel_2.Text = {'Frequency Range'; '(from,to,steps)'};
            app.EventNumberSelectionEditField_2 = uieditfield(app.FieldTripEventAnalysisUIFigure, 'text');
            app.EventNumberSelectionEditField_2.Value = '1,80,1';
            app.TriggerNumberSelectionMatlabExpressionsLabel_4 = uilabel(app.FieldTripEventAnalysisUIFigure);
            app.TriggerNumberSelectionMatlabExpressionsLabel_4.Text = {'Length Time'; 'Bins (s)'};
            app.EventNumberSelectionEditField_5 = uieditfield(app.FieldTripEventAnalysisUIFigure, 'text');
            app.EventNumberSelectionEditField_5.Value = '0.001';
            app.TimeFrequencyPowerandConnectivityAnalysisLabel = uilabel(app.FieldTripEventAnalysisUIFigure);
            app.TimeFrequencyPowerandConnectivityAnalysisLabel.Text = 'Time Frequency Power and Connectivity Analysis';
            app.TFMethodDropDownLabel = uilabel(app.FieldTripEventAnalysisUIFigure);
            app.TFMethodDropDownLabel.Text = 'TF Method';
            app.TFMethodDropDown = uidropdown(app.FieldTripEventAnalysisUIFigure);
            app.TFMethodDropDown.Items = {'Wavelet', 'mtmconvol'};
            app.TFMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @TFMethodDropDownValueChanged, true);
            app.TFMethodDropDown.Value = 'Wavelet';
            % Show the figure after all components are created
            app.FieldTripEventAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FieldTrip_Event_Analysis_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.FieldTripEventAnalysisUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.FieldTripEventAnalysisUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FieldTripEventAnalysisUIFigure)
        end
    end
end