classdef Automatic_Curation_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AutomaticCurationWindowUIFigure  matlab.ui.Figure
        AmplitudeScalingFactorEditField_6  matlab.ui.control.EditField
        MedianAmplitudeCheckBox    matlab.ui.control.CheckBox
        AmplitudeScalingFactorEditField_5  matlab.ui.control.EditField
        NoiseCutoffCheckBox        matlab.ui.control.CheckBox
        AmplitudeScalingFactorEditField_4  matlab.ui.control.EditField
        AmplitudeScalingFactorEditField_3  matlab.ui.control.EditField
        AmplitudeScalingFactorEditField_2  matlab.ui.control.EditField
        AmplitudeScalingFactorEditField  matlab.ui.control.EditField
        NoiseRatioCheckBox         matlab.ui.control.CheckBox
        FiringRangeCheckBox        matlab.ui.control.CheckBox
        ISIViolationRatioCheckBox  matlab.ui.control.CheckBox
        SNRCheckBox                matlab.ui.control.CheckBox
        InformationTextArea        matlab.ui.control.TextArea
        InformationTextAreaLabel   matlab.ui.control.Label
        CloseButton                matlab.ui.control.Button
        SetCurationSettingsButton  matlab.ui.control.Button
    end

    
    properties (Access = public)
        Mainapp = []; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            
            movegui(app.AutomaticCurationWindowUIFigure, 'center');
            app.Mainapp = Mainapp;
            
            app = Utility_Change_Light_Dark_Mode(app,"Automatic_Curation_Window");
            
            if ~isempty(app.Mainapp.LoadfromKilosortWindowWindow.SelectedCurationMethods)
                if isfield(app.Mainapp.LoadfromKilosortWindowWindow.SelectedCurationMethods,'SNR')
                    app.SNRCheckBox.Value = 1;
                    app.AmplitudeScalingFactorEditField.Value = app.Mainapp.LoadfromKilosortWindowWindow.SelectedCurationMethods.SNR;
                end
                if isfield(app.Mainapp.LoadfromKilosortWindowWindow.SelectedCurationMethods,'ISIViolationRatio')
                    app.ISIViolationRatioCheckBox.Value = 1;
                    app.AmplitudeScalingFactorEditField_2.Value = app.Mainapp.LoadfromKilosortWindowWindow.SelectedCurationMethods.ISIViolationRatio;
                end
                if isfield(app.Mainapp.LoadfromKilosortWindowWindow.SelectedCurationMethods,'FiringRange')
                    app.FiringRangeCheckBox.Value = 1;
                    app.AmplitudeScalingFactorEditField_3.Value = app.Mainapp.LoadfromKilosortWindowWindow.SelectedCurationMethods.FiringRange;
                end
                if isfield(app.Mainapp.LoadfromKilosortWindowWindow.SelectedCurationMethods,'NoiseRatio')
                    app.NoiseRatioCheckBox.Value = 1;
                    app.AmplitudeScalingFactorEditField_4.Value = app.Mainapp.LoadfromKilosortWindowWindow.SelectedCurationMethods.NoiseRatio;
                end
                if isfield(app.Mainapp.LoadfromKilosortWindowWindow.SelectedCurationMethods,'NoiseCutOff')
                    app.NoiseCutoffCheckBox.Value = 1;
                    app.AmplitudeScalingFactorEditField_5.Value = app.Mainapp.LoadfromKilosortWindowWindow.SelectedCurationMethods.NoiseCutOff;
                end
                if isfield(app.Mainapp.LoadfromKilosortWindowWindow.SelectedCurationMethods,'MedianAmplitude')
                    app.MedianAmplitudeCheckBox.Value = 1;
                    app.AmplitudeScalingFactorEditField_6.Value = app.Mainapp.LoadfromKilosortWindowWindow.SelectedCurationMethods.MedianAmplitude;
                end
            end
            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            try
                if ~isempty(app.Mainapp.LoadfromKilosortWindowWindow.AutomaticCurationWindow)
                    app.Mainapp.LoadfromKilosortWindowWindow.AutomaticCurationWindow = [];
                end
            catch

            end

            delete(app)
        end

        % Close request function: AutomaticCurationWindowUIFigure
        function AutomaticCurationWindowUIFigureCloseRequest(app, event)

            try
                if ~isempty(app.Mainapp.LoadfromKilosortWindowWindow.AutomaticCurationWindow)
                    app.Mainapp.LoadfromKilosortWindowWindow.AutomaticCurationWindow = [];
                end
            catch

            end

            delete(app)
        end

        % Button pushed function: SetCurationSettingsButton
        function SetCurationSettingsButtonPushed(app, event)
            app.Mainapp.LoadfromKilosortWindowWindow.SelectedCurationMethods = [];
            if app.SNRCheckBox.Value == 1
                app.Mainapp.LoadfromKilosortWindowWindow.SelectedCurationMethods.SNR = app.AmplitudeScalingFactorEditField.Value;
            end
            if app.ISIViolationRatioCheckBox.Value == 1
                app.Mainapp.LoadfromKilosortWindowWindow.SelectedCurationMethods.ISIViolationRatio = app.AmplitudeScalingFactorEditField_2.Value;
            end
            if app.FiringRangeCheckBox.Value == 1
                app.Mainapp.LoadfromKilosortWindowWindow.SelectedCurationMethods.FiringRange = app.AmplitudeScalingFactorEditField_3.Value;
            end
            if app.NoiseRatioCheckBox.Value == 1
                app.Mainapp.LoadfromKilosortWindowWindow.SelectedCurationMethods.NoiseRatio = app.AmplitudeScalingFactorEditField_4.Value;
            end
            if app.NoiseCutoffCheckBox.Value == 1
                app.Mainapp.LoadfromKilosortWindowWindow.SelectedCurationMethods.NoiseCutOff = app.AmplitudeScalingFactorEditField_5.Value;
            end
            if app.MedianAmplitudeCheckBox.Value == 1
                app.Mainapp.LoadfromKilosortWindowWindow.SelectedCurationMethods.MedianAmplitude = app.AmplitudeScalingFactorEditField_6.Value;
            end

            msgbox("Settings succesfully changed.")

            try
                if ~isempty(app.Mainapp.LoadfromKilosortWindowWindow.AutomaticCurationWindow)
                    app.Mainapp.LoadfromKilosortWindowWindow.AutomaticCurationWindow = [];
                end
            catch

            end

            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AutomaticCurationWindowUIFigure = uifigure('Visible', 'off');
            app.AutomaticCurationWindowUIFigure.Name = 'Automatic Curation Window';
            app.AutomaticCurationWindowUIFigure.Icon = 'Logo.png';
            app.AutomaticCurationWindowUIFigure.CloseRequestFcn = createCallbackFcn(app, @AutomaticCurationWindowUIFigureCloseRequest, true);
            app.SetCurationSettingsButton = uibutton(app.AutomaticCurationWindowUIFigure, 'push');
            app.SetCurationSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SetCurationSettingsButtonPushed, true);
            app.SetCurationSettingsButton.Text = 'Set Curation Settings';
            app.CloseButton = uibutton(app.AutomaticCurationWindowUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.InformationTextAreaLabel = uilabel(app.AutomaticCurationWindowUIFigure);
            app.InformationTextAreaLabel.Text = 'Information';
            app.InformationTextArea = uitextarea(app.AutomaticCurationWindowUIFigure);
            app.InformationTextArea.Value = {'Spike sorting with SpikeInterface yields qualitry metrics for each unit. These can be used to automatically exclude units violating these metrics. Please select which quality metric you want to use by clicking the checkboxes and specify thresholds for each metric you select. Make sure the add a ''<'' or ''>'' at the beginning (no space) to specify if unit metrics should be higher or lower than that threshold. Units violating that threshold are excluded automatically when pressing the ''Set Curation Settings'' button.'};
            app.SNRCheckBox = uicheckbox(app.AutomaticCurationWindowUIFigure);
            app.SNRCheckBox.Text = '   SNR';
            app.ISIViolationRatioCheckBox = uicheckbox(app.AutomaticCurationWindowUIFigure);
            app.ISIViolationRatioCheckBox.Text = '   ISI Violation Ratio';
            app.FiringRangeCheckBox = uicheckbox(app.AutomaticCurationWindowUIFigure);
            app.FiringRangeCheckBox.Text = '   Firing Range';
            app.NoiseRatioCheckBox = uicheckbox(app.AutomaticCurationWindowUIFigure);
            app.NoiseRatioCheckBox.Text = '   Noise Ratio';
            app.AmplitudeScalingFactorEditField = uieditfield(app.AutomaticCurationWindowUIFigure, 'text');
            app.AmplitudeScalingFactorEditField.Value = '<3';
            app.AmplitudeScalingFactorEditField_2 = uieditfield(app.AutomaticCurationWindowUIFigure, 'text');
            app.AmplitudeScalingFactorEditField_2.Value = '>0.5';
            app.AmplitudeScalingFactorEditField_3 = uieditfield(app.AutomaticCurationWindowUIFigure, 'text');
            app.AmplitudeScalingFactorEditField_3.Value = '<0.05';
            app.AmplitudeScalingFactorEditField_4 = uieditfield(app.AutomaticCurationWindowUIFigure, 'text');
            app.AmplitudeScalingFactorEditField_4.Value = '>0.6';
            app.NoiseCutoffCheckBox = uicheckbox(app.AutomaticCurationWindowUIFigure);
            app.NoiseCutoffCheckBox.Text = '   Noise Cutoff';
            app.AmplitudeScalingFactorEditField_5 = uieditfield(app.AutomaticCurationWindowUIFigure, 'text');
            app.AmplitudeScalingFactorEditField_5.Value = '>0.5';
            app.MedianAmplitudeCheckBox = uicheckbox(app.AutomaticCurationWindowUIFigure);
            app.MedianAmplitudeCheckBox.Text = '   Median Amplitude';
            app.AmplitudeScalingFactorEditField_6 = uieditfield(app.AutomaticCurationWindowUIFigure, 'text');
            app.AmplitudeScalingFactorEditField_6.Value = '>-0.001';
            % Show the figure after all components are created
            app.AutomaticCurationWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Automatic_Curation_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.AutomaticCurationWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.AutomaticCurationWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AutomaticCurationWindowUIFigure)
        end
    end
end