classdef Load_Spike_Sorting_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LoadSpikeSortingResultsUIFigure  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        RightPanel                      matlab.ui.container.Panel
        OpenButton                      matlab.ui.control.Button
        LoadButton                      matlab.ui.control.Button
        GeneralOptionsPanel             matlab.ui.container.Panel
        OnlyLoadSpikesinTimeRangefromtoEmptyfornonEditField  matlab.ui.control.EditField
        OnlyLoadSpikesinTimeRangefromtoEmptyfornonEditFieldLabel  matlab.ui.control.Label
        FoldercontainingaSpikeInterfaceandorKilosortfolderLabel  matlab.ui.control.Label
        SelectKilosortFolderManuallyButton  matlab.ui.control.Button
        SpikeSorterDropDown             matlab.ui.control.DropDown
        SpikeSorterDropDownLabel        matlab.ui.control.Label
        GeneralOptionsLabel             matlab.ui.control.Label
        OpenWithCurationSoftwarePanel   matlab.ui.container.Panel
        OpenWithCurationSoftwareLabel   matlab.ui.control.Label
        CurationSoftwaretoOpenDropDown  matlab.ui.control.DropDown
        CurationSoftwaretoOpenDropDownLabel  matlab.ui.control.Label
        LoadingOptionsPanel             matlab.ui.container.Panel
        SpikeChannelTypeDropDown        matlab.ui.control.DropDown
        SpikeChannelTypeDropDownLabel   matlab.ui.control.Label
        AutomaticCurationButton         matlab.ui.control.Button
        DeleteMUACheckBox               matlab.ui.control.CheckBox
        LoadingOptionsLabel             matlab.ui.control.Label
        SelectAmplitudeScalingManuallyButton  matlab.ui.control.Button
        EmptyfornonLabel                matlab.ui.control.Label
        AmplitudeScalingFactorEditField  matlab.ui.control.EditField
        AmplitudeScalingFactorEditFieldLabel  matlab.ui.control.Label
        InformationTextArea             matlab.ui.control.TextArea
        InformationTextAreaLabel        matlab.ui.control.Label
        CloseButton                     matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Mainapp=[]; % Description
        Path = [];
        CurrentSorter = [];
        ManualSelection = 0;
        AutomaticCurationWindow = [];
        SelectedCurationMethods = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            movegui(app.LoadSpikeSortingResultsUIFigure, 'center');
            app.Mainapp = Mainapp;
            
            app = Utility_Change_Light_Dark_Mode(app,"Load_Sorting_Window");

            SoterFolders(1) = strcat(app.Mainapp.Data.Info.Data_Path,"\Kilosort\kilosort4");
            SoterFolders(2) = strcat(app.Mainapp.Data.Info.Data_Path,"\Kilosort\kilosort3");
            SoterFolders(3) = strcat(app.Mainapp.Data.Info.Data_Path,"\SpikeInterface\SpikeInterface_Sorting_Phy_Results\Mountainsort 5");
            SoterFolders(4) = strcat(app.Mainapp.Data.Info.Data_Path,"\SpikeInterface\SpikeInterface_Sorting_Phy_Results\SpyKING CIRCUS 2");
            SoterFolders(5) = strcat(app.Mainapp.Data.Info.Data_Path,"\Wave_Clus");
            SoterFolders(6) = strcat(app.Mainapp.Data.Info.Data_Path,"\SpikeInterface\SpikeInterface_Sorting_Phy_Results\Kilosort 4");
            
            app.Path = app.Mainapp.Data.Info.Data_Path;

            [~,app.CurrentSorter,app.AmplitudeScalingFactorEditField.Value,InfoText] = Spike_Module_Determine_Sorters_Available(app.Mainapp.Data,SoterFolders,0,0);

            if strcmp(app.CurrentSorter,"Mountainsort5") 
                app.SpikeSorterDropDown.Value = app.SpikeSorterDropDown.Items{3};
            elseif strcmp(app.CurrentSorter,"SpykingCircus2")
                app.SpikeSorterDropDown.Value = app.SpikeSorterDropDown.Items{4};
            elseif strcmp(app.CurrentSorter,"Kilosort4")
                app.SpikeSorterDropDown.Value = app.SpikeSorterDropDown.Items{1};
            elseif strcmp(app.CurrentSorter,"Kilosort3")
                app.SpikeSorterDropDown.Value = app.SpikeSorterDropDown.Items{2};
            elseif strcmp(app.CurrentSorter,"SpikeInterface Kilosort") 
                app.SpikeSorterDropDown.Value = app.SpikeSorterDropDown.Items{6};
            elseif strcmp(app.CurrentSorter,"WaveClus")
                app.SpikeSorterDropDown.Value = app.SpikeSorterDropDown.Items{5};
            end

            if ~strcmp(app.CurrentSorter,"Kilosort4") && ~strcmp(app.CurrentSorter,"Kilosort3")
                app.AmplitudeScalingFactorEditField.Enable = "off";
                app.AmplitudeScalingFactorEditField.Value = '';
                app.SelectAmplitudeScalingManuallyButton.Enable = "off";
            else
                app.SelectAmplitudeScalingManuallyButton.Enable = "on";
                app.AmplitudeScalingFactorEditField.Enable = "on";
            end

            app.InformationTextArea.Value = InfoText;

            if strcmp(app.SpikeSorterDropDown.Value,"Kilosort 4 external GUI")
                app.DeleteMUACheckBox.Enable = "on";
                app.DeleteMUACheckBox.Visible = "on";

                app.AutomaticCurationButton.Enable = "off";
                app.AutomaticCurationButton.Visible = "off";
            else
                app.DeleteMUACheckBox.Enable = "off";
                app.DeleteMUACheckBox.Visible = "off";

                app.AutomaticCurationButton.Enable = "on";
                app.AutomaticCurationButton.Visible = "on";
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            if isempty(app.Path)
                msgbox("No Path selected. Please first select a folder.");
                return;
            end

            if ~isfolder(app.Path)
                msgbox("Selected Path does not exist. Please select an exisitng folder.");
                return;
            end

            if isempty(app.CurrentSorter)
                msgbox("No valid sorting files found in selected folder.");
                return;
            end
            
            if isempty(app.AmplitudeScalingFactorEditField.Value)
                KilosortScalingFactor = [];
            else
                KilosortScalingFactor = str2double(app.AmplitudeScalingFactorEditField.Value);
            end

            if strcmp(app.CurrentSorter,"Kilosort4") 
                LoadFolder = strcat(app.Path,"\Kilosort\kilosort4");
            elseif strcmp(app.CurrentSorter,"Kilosort3")
                LoadFolder = strcat(app.Path,"\Kilosort\kilosort3");
            elseif strcmp(app.CurrentSorter,"Mountainsort5")
                LoadFolder = strcat(app.Path,"\SpikeInterface\SpikeInterface_Sorting_Phy_Results\Mountainsort 5");
            elseif strcmp(app.CurrentSorter,"SpykingCircus2")
                LoadFolder = strcat(app.Path,"\SpikeInterface\SpikeInterface_Sorting_Phy_Results\SpyKING CIRCUS 2");
            elseif strcmp(app.CurrentSorter,"WaveClus")
                LoadFolder = strcat(app.Path,"\Wave_Clus");
            elseif strcmp(app.CurrentSorter,"SpikeInterface Kilosort")
                LoadFolder = strcat(app.Path,"\SpikeInterface\SpikeInterface_Sorting_Phy_Results\Kilosort 4");
            end
                        
            if strcmp(app.SpikeSorterDropDown.Value,"Mountainsort 5") || strcmp(app.SpikeSorterDropDown.Value,"SpykingCircus 2") || strcmp(app.SpikeSorterDropDown.Value,"Kilosort SpikeInterface")
                [app.Mainapp.Data,SaveFilter] = Spike_Module_Load_SpikeInterface_Sorter(app.Mainapp.Data,LoadFolder,app.CurrentSorter,app.SelectedCurationMethods,app.SpikeChannelTypeDropDown.Value,app.OnlyLoadSpikesinTimeRangefromtoEmptyfornonEditField.Value,app.Mainapp.ParallelPool);
            end

            if strcmp(app.SpikeSorterDropDown.Value,"Kilosort 4 external GUI") || strcmp(app.SpikeSorterDropDown.Value,"Kilosort 3 external GUI")
                % Function to load all relevant npy and .mat files Kilosort outputs
                [app.Mainapp.Data,SaveFilter] = Spike_Module_Load_Kilosort_Data(app.Mainapp.Data,"No",LoadFolder,KilosortScalingFactor,app.SpikeSorterDropDown.Value,app.DeleteMUACheckBox.Value,app.SpikeChannelTypeDropDown.Value,app.OnlyLoadSpikesinTimeRangefromtoEmptyfornonEditField.Value,app.Mainapp.ParallelPool);
            end

            if strcmp(app.SpikeSorterDropDown.Value,"WaveClus 3")
                %if isfield(app.Mainapp.Data,'Spikes') && strcmp(app.Mainapp.Data.Info.SpikeType,'Internal')
                    [app.Mainapp.Data] = Spike_Module_Internal_Spike_Sorting(app.Mainapp.Data,LoadFolder,"Loading",[],app.CurrentSorter);
                    SaveFilter = "No";
                %else
                    %msgbox("To load WaveClus sorting, you first have to conduct spike detection in the upper part of the 'Spike Detection and Sorting' window! Skipping.")
                    %return;
                %end
            end

            [app.Mainapp] = Organize_Set_MainWindow_Dropdown(app.Mainapp,app.Mainapp.Data);

            Utility_Show_Info_Loaded_Data(app.Mainapp);
            
            if strcmp(SaveFilter,"Yes")
                [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Preprocessing",app.Mainapp.Data,[],[],[],[],[]);
            end

            KeepSpikes = 0;
            KeepEvents = 0;
            if isfield(app.Mainapp.Data,'Spikes') && strcmp(app.Mainapp.Plotspikes,"Spikes")
                KeepSpikes = 1;
            end
            if isfield(app.Mainapp.Data,'Events') && strcmp(app.Mainapp.PlotEvents,"Events")
                KeepEvents = 1;
            end
            
            Organize_Reset_Main_Plot(app.Mainapp,1,KeepEvents,KeepSpikes,0,app.Mainapp.CurrentTimePoints);
            
            app.Mainapp = Utility_Change_Light_Dark_Mode(app.Mainapp,"MainWindow");

            % overlay spike pos over probe
            if ~isempty(app.Mainapp.ProbeViewWindowHandle)
                if isfield(app.Mainapp.Data,'Spikes') && strcmp(app.Mainapp.Plotspikes,"Spikes")
                    if ~isempty(app.Mainapp.Data.Spikes)
                        if ~isempty(app.Mainapp.ProbeViewWindowHandle)
                            if isvalid(app.Mainapp.ProbeViewWindowHandle)
                               ProbeView_Show_Spike_Positions(app.Mainapp.Data,app.Mainapp.ProbeViewProperties,app.Mainapp.ProbeViewWindowHandle.UIAxes,app.Mainapp.ProbeViewWindowHandle.SpikeCircleSizeMethod)
                            end
                        end
                    else
                        SpikeHandles = findobj(app.Mainapp.ProbeViewWindowHandle.UIAxes, 'Tag', 'SpikePositions');
                        if ~isempty(SpikeHandles)
                            delete(SpikeHandles)
                        end
                    end
                else
                    if ~isempty(app.Mainapp.ProbeViewWindowHandle)
                        SpikeHandles = findobj(app.Mainapp.ProbeViewWindowHandle.UIAxes, 'Tag', 'SpikePositions');
                        if ~isempty(SpikeHandles)
                            delete(SpikeHandles)
                        end
                    end
                end
            end

            if ~isempty(app.Mainapp.SpikeSelectorWindow)
                try
                    delete(app.Mainapp.SpikeSelectorWindow);
                    app.Mainapp.SpikeSelectorWindow = [];
                    
                catch

                end
            end

            delete(app);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.Mainapp.LoadfromKilosortWindowWindow = [];
            try
                if ~isempty(app.AutomaticCurationWindow)
                    delete(app.AutomaticCurationWindow)
                    app.AutomaticCurationWindow = [];
                end
            catch

            end

            delete(app);
        end

        % Button pushed function: SelectKilosortFolderManuallyButton
        function SelectKilosortFolderManuallyButtonPushed(app, event)
            
            app.ManualSelection = 1;
            
            SorterSelected = 0;
            % Prompt user to select a folder
            folderPath = uigetdir(app.Mainapp.executableFolder,'Select a folder');
            
            % Check if user cancels the dialog
            if folderPath == 0
                disp('Operation canceled by user');
                return;
            end

            SoterFolders(1) = strcat(folderPath,"\Kilosort\kilosort4");
            SoterFolders(2) = strcat(folderPath,"\Kilosort\kilosort3");
            SoterFolders(3) = strcat(folderPath,"\SpikeInterface\SpikeInterface_Sorting_Phy_Results\Mountainsort 5");
            SoterFolders(4) = strcat(folderPath,"\SpikeInterface\SpikeInterface_Sorting_Phy_Results\SpyKING CIRCUS 2");
            SoterFolders(5) = strcat(folderPath,"\Wave_Clus");
            SoterFolders(6) = strcat(folderPath,"\SpikeInterface\SpikeInterface_Sorting_Phy_Results\Kilosort 4");
            
            app.Path = folderPath;

            [~,app.CurrentSorter,app.AmplitudeScalingFactorEditField.Value,InfoText] = Spike_Module_Determine_Sorters_Available(app.Mainapp.Data,SoterFolders,0,SorterSelected);
            
            
            if strcmp(app.CurrentSorter,"Mountainsort5") 
                app.SpikeSorterDropDown.Value = app.SpikeSorterDropDown.Items{3};
            elseif strcmp(app.CurrentSorter,"SpykingCircus2")
                app.SpikeSorterDropDown.Value = app.SpikeSorterDropDown.Items{4};
            elseif strcmp(app.CurrentSorter,"Kilosort3") 
                app.SpikeSorterDropDown.Value = app.SpikeSorterDropDown.Items{2};
            elseif strcmp(app.CurrentSorter,"Kilosort4")
                app.SpikeSorterDropDown.Value = app.SpikeSorterDropDown.Items{1};
            elseif strcmp(app.CurrentSorter,"WaveClus")
                app.SpikeSorterDropDown.Value = app.SpikeSorterDropDown.Items{5};
            end

            app.InformationTextArea.Value = InfoText;
        end

        % Button pushed function: SelectAmplitudeScalingManuallyButton
        function SelectAmplitudeScalingManuallyButtonPushed(app, event)
            % Prompt user to select a folder
            filePath = uigetfile(app.Mainapp.executableFolder,'Select a folder');
            
            % Check if user cancels the dialog
            if filePath == 0
                disp('Operation canceled by user');
                return;
            end
            
            %% Check if selected mat file contains correct variable
            variableName = 'scalingFactor';  % Variable you want to load
            
            % Get the list of variables in the file
            variablesInFile = who('-file', filePath);
            
            % Check if the desired variable exists
            if ismember(variableName, variablesInFile)
                load(filePath, variableName);  % Load only the specific variable
            else
                msgbox(strcat("Variable ", variableName," does not exist in the manually selected file ",filePath));
                return;  % Exit if the variable does not exist
            end
            
            load(filePath,'scalingFactor');
            
            scalindicie = find(contains(app.InformationTextArea.Value, 'Scaling'));
            % cut out previous scaling info shown
            StringtoShow = app.InformationTextArea.Value(1:scalindicie(1)-1);
            % add new scaling info 
            StringtoShow = [StringtoShow; "Scaling factor to convert int format of the kilosort output to mV found in manually selected a .mat file."];
            
            
            app.AmplitudeScalingFactorEditField.Value = num2str(scalingFactor);
            app.Mainapp.Data.Info.KilosortScalingFactor = scalingFactor;

            app.InformationTextArea.Value = StringtoShow;
        end

        % Close request function: LoadSpikeSortingResultsUIFigure
        function LoadSpikeSortingResultsUIFigureCloseRequest(app, event)
            app.Mainapp.LoadfromKilosortWindowWindow = [];
            try
                if ~isempty(app.AutomaticCurationWindow)
                    delete(app.AutomaticCurationWindow)
                    app.AutomaticCurationWindow = [];
                end
            catch

            end
            delete(app);
        end

        % Value changed function: SpikeSorterDropDown
        function SpikeSorterDropDownValueChanged(app, event)


            if strcmp(app.SpikeSorterDropDown.Value,"Kilosort 4 external GUI") || strcmp(app.SpikeSorterDropDown.Value,"Kilosort 3 external GUI")
                app.AmplitudeScalingFactorEditField.Enable = "on";
                app.AmplitudeScalingFactorEditField.Value = '';
                app.SelectAmplitudeScalingManuallyButton.Enable = "on";

                app.DeleteMUACheckBox.Enable = "on";
                app.DeleteMUACheckBox.Visible = "on";

                app.AutomaticCurationButton.Enable = "off";
                app.AutomaticCurationButton.Visible = "off";
            else
                app.AmplitudeScalingFactorEditField.Enable = "off";
                app.AmplitudeScalingFactorEditField.Value = '';
                app.SelectAmplitudeScalingManuallyButton.Enable = "off";

                app.DeleteMUACheckBox.Enable = "off";
                app.DeleteMUACheckBox.Visible = "off";

                app.AutomaticCurationButton.Enable = "on";
                app.AutomaticCurationButton.Visible = "on";
            end
            
            app.SelectedCurationMethods = [];

            if app.ManualSelection == 0
                if strcmp(app.SpikeSorterDropDown.Value,"Kilosort 4 external GUI")
                    SoterFolders(1) = strcat(app.Path,"\Kilosort\kilosort4");
                    SoterFolders(2) = "";
                    SoterFolders(3) = "";
                    SoterFolders(4) = "";          
                    SoterFolders(5) = "";  
                    SoterFolders(6) = ""; 
                elseif strcmp(app.SpikeSorterDropDown.Value,"Kilosort 3 external GUI")
                    SoterFolders(1) = "";
                    SoterFolders(2) = strcat(app.Path,"\Kilosort\kilosort3");
                    SoterFolders(3) = "";
                    SoterFolders(4) = "";          
                    SoterFolders(5) = "";  
                    SoterFolders(6) = ""; 
                elseif strcmp(app.SpikeSorterDropDown.Value,"Kilosort SpikeInterface")
                    SoterFolders(1) = "";
                    SoterFolders(2) = "";
                    SoterFolders(3) = "";
                    SoterFolders(4) = "";          
                    SoterFolders(5) = "";  
                    SoterFolders(6) = strcat(app.Path,"\SpikeInterface\SpikeInterface_Sorting_Phy_Results\Kilosort 4");
                elseif strcmp(app.SpikeSorterDropDown.Value,"Mountainsort 5")
                    SoterFolders(1) = "";
                    SoterFolders(2) = "";
                    SoterFolders(3) = strcat(app.Path,"\SpikeInterface\SpikeInterface_Sorting_Phy_Results\Mountainsort 5");
                    SoterFolders(4) = "";
                    SoterFolders(5) = ""; 
                    SoterFolders(6) = "";
                elseif strcmp(app.SpikeSorterDropDown.Value,"SpykingCircus 2")
                    SoterFolders(1) = "";
                    SoterFolders(2) = "";
                    SoterFolders(3) = "";
                    SoterFolders(4) = strcat(app.Path,"\SpikeInterface\SpikeInterface_Sorting_Phy_Results\SpyKING CIRCUS 2");
                    SoterFolders(5) = ""; 
                    SoterFolders(6) = "";
                elseif strcmp(app.SpikeSorterDropDown.Value,"WaveClus 3")
                    SoterFolders(1) = "";
                    SoterFolders(2) = "";
                    SoterFolders(3) = "";
                    SoterFolders(4) = "";
                    SoterFolders(5) = strcat(app.Path,"\Wave_Clus"); 
                    SoterFolders(6) = "";
                end

                SorterSelected = 0;

            else

                SorterSelected = 0;

                if strcmp(app.SpikeSorterDropDown.Value,"Kilosort 4 external GUI")
                    SoterFolders(1) = strcat(app.Path,"\Kilosort\kilosort4");
                    SoterFolders(2) = "";
                    SoterFolders(3) = "";
                    SoterFolders(4) = "";          
                    SoterFolders(5) = "";  
                    SoterFolders(6) = ""; 
                elseif strcmp(app.SpikeSorterDropDown.Value,"Kilosort 3 external GUI")
                    SoterFolders(1) = "";
                    SoterFolders(2) = strcat(app.Path,"\Kilosort\kilosort3");
                    SoterFolders(3) = "";
                    SoterFolders(4) = "";          
                    SoterFolders(5) = "";  
                    SoterFolders(6) = ""; 
                elseif strcmp(app.SpikeSorterDropDown.Value,"Kilosort SpikeInterface")
                    SoterFolders(1) = "";
                    SoterFolders(2) = "";
                    SoterFolders(3) = "";
                    SoterFolders(4) = "";          
                    SoterFolders(5) = "";  
                    SoterFolders(6) = strcat(app.Path,"\SpikeInterface\SpikeInterface_Sorting_Phy_Results\Kilosort 4");
                elseif strcmp(app.SpikeSorterDropDown.Value,"Mountainsort 5")
                    SoterFolders(1) = "";
                    SoterFolders(2) = "";
                    SoterFolders(3) = strcat(app.Path,"\SpikeInterface\SpikeInterface_Sorting_Phy_Results\Mountainsort 5");
                    SoterFolders(4) = "";
                    SoterFolders(5) = ""; 
                    SoterFolders(6) = "";
                elseif strcmp(app.SpikeSorterDropDown.Value,"SpykingCircus 2")
                    SoterFolders(1) = "";
                    SoterFolders(2) = "";
                    SoterFolders(3) = "";
                    SoterFolders(4) = strcat(app.Path,"\SpikeInterface\SpikeInterface_Sorting_Phy_Results\SpyKING CIRCUS 2");
                    SoterFolders(5) = ""; 
                    SoterFolders(6) = "";
                elseif strcmp(app.SpikeSorterDropDown.Value,"WaveClus 3")
                    SoterFolders(1) = "";
                    SoterFolders(2) = "";
                    SoterFolders(3) = "";
                    SoterFolders(4) = "";
                    SoterFolders(5) = strcat(app.Path,"\Wave_Clus"); 
                    SoterFolders(6) = "";
                end
            end


            [~,app.CurrentSorter,app.AmplitudeScalingFactorEditField.Value,InfoText] = Spike_Module_Determine_Sorters_Available(app.Mainapp.Data,SoterFolders,0,SorterSelected);

            if ~strcmp(app.SpikeSorterDropDown.Value,"Kilosort 4 external GUI") && ~strcmp(app.SpikeSorterDropDown.Value,"Kilosort 3 external GUI")
                app.AmplitudeScalingFactorEditField.Value = "";
            end
           
            app.InformationTextArea.Value = InfoText;

            
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            if isempty(app.CurrentSorter)
                msgbox("Not output files for selected sorter found to open!")
                return
            end
            
            if strcmp(app.SpikeSorterDropDown.Value,"WaveClus 3") 
                msgbox("WaveClus results cannot be openend in curation software!")
                return
            end

            if strcmp(app.SpikeSorterDropDown.Value,"Mountainsort 5")
                ResultsFile = strcat(app.Path,"\SpikeInterface\SpikeInterface_Sorting_Phy_Results\Mountainsort 5");
            elseif strcmp(app.SpikeSorterDropDown.Value,"SpykingCircus 2")
                ResultsFile = strcat(app.Path,"\SpikeInterface\SpikeInterface_Sorting_Phy_Results\SpyKING CIRCUS 2");
            elseif strcmp(app.SpikeSorterDropDown.Value,"Kilosort 4 external GUI")
                ResultsFile = strcat(app.Path,"\Kilosort\kilosort4");
            end

            if strcmp(app.CurationSoftwaretoOpenDropDown.Value,"Phy2")
                [~] = Spike_Module_Start_Phy(app.Mainapp.Data,app.Mainapp.executableFolder,ResultsFile);
            else

            end
        end

        % Button pushed function: AutomaticCurationButton
        function AutomaticCurationButtonPushed(app, event)
            
            app.AutomaticCurationWindow = Automatic_Curation_Window(app.Mainapp);

            [~] = Utility_Set_ToolTips(app.Mainapp,app.Mainapp.ShowToolTipsSetting,"UnitCurationWindow");
        end

        % Value changed function: 
        % OnlyLoadSpikesinTimeRangefromtoEmptyfornonEditField
        function OnlyLoadSpikesinTimeRangefromtoEmptyfornonEditFieldValueChanged(app, event)
            value = app.OnlyLoadSpikesinTimeRangefromtoEmptyfornonEditField.Value;
            if ~isempty(value)
                if ~contains(value,',')
                    msgbox("No comma detected. Please enter two comma separated numbers.");
                    app.OnlyLoadSpikesinTimeRangefromtoEmptyfornonEditField.Value = '';
                end
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.LoadSpikeSortingResultsUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {575, 575};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LoadSpikeSortingResultsUIFigure = uifigure('Visible', 'off');
            app.LoadSpikeSortingResultsUIFigure.Name = 'Load Spike Sorting Results';
            app.LoadSpikeSortingResultsUIFigure.Icon = 'Logo.png';
            app.LoadSpikeSortingResultsUIFigure.CloseRequestFcn = createCallbackFcn(app, @LoadSpikeSortingResultsUIFigureCloseRequest, true);
            app.LoadSpikeSortingResultsUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.LoadSpikeSortingResultsUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.CloseButton = uibutton(app.RightPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.InformationTextAreaLabel = uilabel(app.RightPanel);
            app.InformationTextAreaLabel.Text = 'Information';
            app.InformationTextArea = uitextarea(app.RightPanel);
            app.LoadingOptionsPanel = uipanel(app.RightPanel);
            app.LoadingOptionsPanel.Title = 'Loading Options';
            app.AmplitudeScalingFactorEditFieldLabel = uilabel(app.LoadingOptionsPanel);
            app.AmplitudeScalingFactorEditFieldLabel.Text = {'Amplitude'; 'Scaling Factor'};
            app.AmplitudeScalingFactorEditField = uieditfield(app.LoadingOptionsPanel, 'text');
            app.EmptyfornonLabel = uilabel(app.LoadingOptionsPanel);
            app.EmptyfornonLabel.Text = '(Empty for non)';
            app.SelectAmplitudeScalingManuallyButton = uibutton(app.LoadingOptionsPanel, 'push');
            app.SelectAmplitudeScalingManuallyButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAmplitudeScalingManuallyButtonPushed, true);
            app.SelectAmplitudeScalingManuallyButton.Text = {'Select Amplitude'; 'Scaling Manually'};
            app.LoadingOptionsLabel = uilabel(app.LoadingOptionsPanel);
            app.LoadingOptionsLabel.Text = 'Loading Options';
            app.DeleteMUACheckBox = uicheckbox(app.LoadingOptionsPanel);
            app.DeleteMUACheckBox.Text = '   Delete MUA';
            app.DeleteMUACheckBox.Value = true;
            app.AutomaticCurationButton = uibutton(app.LoadingOptionsPanel, 'push');
            app.AutomaticCurationButton.ButtonPushedFcn = createCallbackFcn(app, @AutomaticCurationButtonPushed, true);
            app.AutomaticCurationButton.Text = 'Automatic Curation';
            app.SpikeChannelTypeDropDownLabel = uilabel(app.LoadingOptionsPanel);
            app.SpikeChannelTypeDropDownLabel.Text = 'Spike Channel Type';
            app.SpikeChannelTypeDropDown = uidropdown(app.LoadingOptionsPanel);
            app.SpikeChannelTypeDropDown.Items = {'Channel closest to X and Y of respective spikes', 'Single channel for all spikes in one unit (max template channel)'};
            app.SpikeChannelTypeDropDown.Value = 'Channel closest to X and Y of respective spikes';
            app.OpenWithCurationSoftwarePanel = uipanel(app.RightPanel);
            app.OpenWithCurationSoftwarePanel.Title = 'Open With Curation Software';
            app.CurationSoftwaretoOpenDropDownLabel = uilabel(app.OpenWithCurationSoftwarePanel);
            app.CurationSoftwaretoOpenDropDownLabel.Text = 'Curation Software to Open';
            app.CurationSoftwaretoOpenDropDown = uidropdown(app.OpenWithCurationSoftwarePanel);
            app.CurationSoftwaretoOpenDropDown.Items = {'Phy2'};
            app.CurationSoftwaretoOpenDropDown.Value = 'Phy2';
            app.OpenWithCurationSoftwareLabel = uilabel(app.OpenWithCurationSoftwarePanel);
            app.OpenWithCurationSoftwareLabel.Text = 'Open With Curation Software';
            app.GeneralOptionsPanel = uipanel(app.RightPanel);
            app.GeneralOptionsPanel.Title = 'General Options';
            app.GeneralOptionsLabel = uilabel(app.GeneralOptionsPanel);
            app.GeneralOptionsLabel.Text = 'General Options';
            app.SpikeSorterDropDownLabel = uilabel(app.GeneralOptionsPanel);
            app.SpikeSorterDropDownLabel.Text = 'SpikeSorter';
            app.SpikeSorterDropDown = uidropdown(app.GeneralOptionsPanel);
            app.SpikeSorterDropDown.Items = {'Kilosort 4 external GUI', 'Kilosort 3 external GUI', 'Mountainsort 5', 'SpykingCircus 2', 'WaveClus 3', 'Kilosort SpikeInterface'};
            app.SpikeSorterDropDown.ValueChangedFcn = createCallbackFcn(app, @SpikeSorterDropDownValueChanged, true);
            app.SpikeSorterDropDown.Value = 'Kilosort 4 external GUI';
            app.SelectKilosortFolderManuallyButton = uibutton(app.GeneralOptionsPanel, 'push');
            app.SelectKilosortFolderManuallyButton.ButtonPushedFcn = createCallbackFcn(app, @SelectKilosortFolderManuallyButtonPushed, true);
            app.SelectKilosortFolderManuallyButton.Text = {'Manually Select'; 'Folder'};
            app.FoldercontainingaSpikeInterfaceandorKilosortfolderLabel = uilabel(app.GeneralOptionsPanel);
            app.FoldercontainingaSpikeInterfaceandorKilosortfolderLabel.Text = {'Folder containing a ''SpikeInterface'''; '''Kilosort'' and/or ''Wave_Clus'' folder'};
            app.OnlyLoadSpikesinTimeRangefromtoEmptyfornonEditFieldLabel = uilabel(app.GeneralOptionsPanel);
            app.OnlyLoadSpikesinTimeRangefromtoEmptyfornonEditFieldLabel.Text = {'Only Load Spikes in'; 'Time Range (from,to)'; '(Empty for non)'};
            app.OnlyLoadSpikesinTimeRangefromtoEmptyfornonEditField = uieditfield(app.GeneralOptionsPanel, 'text');
            app.OnlyLoadSpikesinTimeRangefromtoEmptyfornonEditField.ValueChangedFcn = createCallbackFcn(app, @OnlyLoadSpikesinTimeRangefromtoEmptyfornonEditFieldValueChanged, true);
            app.LoadButton = uibutton(app.RightPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.OpenButton = uibutton(app.RightPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            % Show the figure after all components are created
            app.LoadSpikeSortingResultsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Load_Spike_Sorting_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.LoadSpikeSortingResultsUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.LoadSpikeSortingResultsUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LoadSpikeSortingResultsUIFigure)
        end
    end
end