classdef SpikeInterface_SpikeSorting_Parameter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpikeInterfaceParameterSelectionUIFigure  matlab.ui.Figure
        ModifyValuesaftertheLabel       matlab.ui.control.Label
        SaveSettingsButton              matlab.ui.control.Button
        RestoreDefaultButton            matlab.ui.control.Button
        SortingParameterLabel           matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        CloseButton                     matlab.ui.control.Button
        SetNewSettingsandContinueButton  matlab.ui.control.Button
        GeneralSettingsPanel            matlab.ui.container.Panel
        GeneralSettingsLabel            matlab.ui.control.Label
        LoopovermultipleRecordingsCheckBox  matlab.ui.control.CheckBox
        PreprocessDataBandPassandWhiteningCheckBox  matlab.ui.control.CheckBox
        KeepConsoleOpen                 matlab.ui.control.CheckBox
        LoadButton                      matlab.ui.control.Button
        LoadSavedParameterDropDown      matlab.ui.control.DropDown
        LoadSavedParameterDropDownLabel  matlab.ui.control.Label
        SorterMountainsort5Label        matlab.ui.control.Label
        LoadSpikeSortingCheckBox        matlab.ui.control.CheckBox
        PlotTracesCheckBox              matlab.ui.control.CheckBox
        PlotsomesortingresultsCheckBox  matlab.ui.control.CheckBox
        OpenSpikeInterfaceGUIaftersortingCheckBox  matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        Mainapp = []; % Description
        SpikeDetectionapp = [];
        SelectedSettings = [];
        ParameterStructure = [];
        SaveName = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp, SpikeDetectionapp)
            movegui(app.SpikeInterfaceParameterSelectionUIFigure, 'center');

            app.Mainapp = Mainapp;
            app.SpikeDetectionapp = SpikeDetectionapp;
            
            app = Utility_Change_Light_Dark_Mode(app,"Spike_Sorting_Parameter");

            if strcmp(app.SpikeDetectionapp.SorterDropDown.Value,"SpyKING CIRCUS 2")
                app.LoadSpikeSortingCheckBox.Enable = "off";
                app.LoadSpikeSortingCheckBox.Value = 0;
            end

            if ~isempty(app.SpikeDetectionapp.SpikeInterfaceParameter)
                app.OpenSpikeInterfaceGUIaftersortingCheckBox.Value = app.SpikeDetectionapp.SpikeInterfaceParameter.OpenSpikeInterface;
                app.PreprocessDataBandPassandWhiteningCheckBox.Value = app.SpikeDetectionapp.SpikeInterfaceParameter.Preprocess;
                app.PlotTracesCheckBox.Value = app.SpikeDetectionapp.SpikeInterfaceParameter.PlotTraces;
                app.PlotsomesortingresultsCheckBox.Value = app.SpikeDetectionapp.SpikeInterfaceParameter.PlotSortingResults;
                app.LoadSpikeSortingCheckBox.Value = app.SpikeDetectionapp.SpikeInterfaceParameter.LoadSorting;
                app.LoopovermultipleRecordingsCheckBox.Value = app.SpikeDetectionapp.SpikeInterfaceParameter.MultipleRecordings;
                if isfield(app.SpikeDetectionapp.SpikeInterfaceParameter,'KeepConsoleOpen')
                    app.KeepConsoleOpen.Value = app.SpikeDetectionapp.SpikeInterfaceParameter.KeepConsoleOpen;
                else
                    app.KeepConsoleOpen.Value = 0;
                end

                if app.OpenSpikeInterfaceGUIaftersortingCheckBox.Value ==1 
                    app.PlotTracesCheckBox.Enable = "off";
                    app.PlotsomesortingresultsCheckBox.Enable = "off";
                    app.PlotTracesCheckBox.Value = 0;
                    app.PlotsomesortingresultsCheckBox.Value = 0;
                else
                    app.PlotTracesCheckBox.Enable = "on";
                    app.PlotsomesortingresultsCheckBox.Enable = "on";
                end
            else
                app.PlotTracesCheckBox.Enable = "off";
                app.PlotsomesortingresultsCheckBox.Enable = "off";
            end

            app.SorterMountainsort5Label.Text = strcat("Sorter: ",app.SpikeDetectionapp.SorterDropDown.Value);
            
            if isempty(app.SpikeDetectionapp.ParameterStructure)
                [app.SpikeDetectionapp.ParameterStructure,app.TextArea.Value] = Spike_Module_ShowSpikeSortingSettings(app.SpikeDetectionapp.SorterDropDown.Value,app.SpikeDetectionapp.ParameterStructure,"ParameterNOTPresent");
            else
                [app.SpikeDetectionapp.ParameterStructure,app.TextArea.Value] = Spike_Module_ShowSpikeSortingSettings(app.SpikeDetectionapp.SorterDropDown.Value,app.SpikeDetectionapp.ParameterStructure,"ParameterPresent");
            end
            
            %% Show Saved Parameter
            FolderWithSettings = strcat(app.Mainapp.executableFolder,"\Modules\SpikeInterface\Saved_Spike_Sorting_Parameter");
            [stringArray] = Utility_Extract_Contents_of_Folder(FolderWithSettings);
            stringArray(stringArray=="") = [];
            stringArray(stringArray=='') = [];

            if ~isempty(stringArray)
                for i = 1:length(stringArray)
                    app.LoadSavedParameterDropDown.Items{i} = convertStringsToChars(stringArray(i));
                end
            end
            
        end

        % Button pushed function: SetNewSettingsandContinueButton
        function SetNewSettingsandContinueButtonPushed(app, event)
            app.SelectedSettings.OpenSpikeInterface = double(app.OpenSpikeInterfaceGUIaftersortingCheckBox.Value);
            app.SelectedSettings.Preprocess = double(app.PreprocessDataBandPassandWhiteningCheckBox.Value);
            app.SelectedSettings.PlotTraces = double(app.PlotTracesCheckBox.Value);
            app.SelectedSettings.PlotSortingResults = double(app.PlotsomesortingresultsCheckBox.Value);           
            app.SelectedSettings.Sorter = app.SpikeDetectionapp.SorterDropDown.Value;
            app.SelectedSettings.MultipleRecordings = double(app.LoopovermultipleRecordingsCheckBox.Value);
            app.SelectedSettings.LoadSorting = double(app.LoadSpikeSortingCheckBox.Value);
            app.SelectedSettings.KeepConsoleOpen = double(app.KeepConsoleOpen.Value);
            
            app.SpikeDetectionapp.SpikeInterfaceParameter = app.SelectedSettings;

            app.SpikeDetectionapp.SortingParameterWindow = [];

            if app.SelectedSettings.MultipleRecordings == 1 && app.SpikeDetectionapp.MultipleFolderSelected == 0
                app.SpikeDetectionapp.MultipleFolderSelected = 1;
                app.SpikeDetectionapp = Spike_Module_Check_Bin_Dat_Files(app.SpikeDetectionapp,"MultipleRecordings","");
            else
                app.SpikeDetectionapp.ManualSelection = 0;
                app.SpikeDetectionapp.MultipleFolderSelected = 0;
                app.SpikeDetectionapp = Spike_Module_Check_Bin_Dat_Files(app.SpikeDetectionapp,"Auto","");
            end

            delete(app);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.SpikeDetectionapp.SortingParameterWindow = [];

            delete(app);
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
             
            if strcmp(app.SpikeDetectionapp.SorterDropDown.Value,"Mountainsort 5")
                [Struc] = Spike_Module_ConvertTextToParameterStruc(app.TextArea.Value,app.SpikeDetectionapp.SorterDropDown.Value,app.SpikeDetectionapp.ParameterStructure.MS5);
                % Update the app's data structure
                app.SpikeDetectionapp.ParameterStructure.MS5 = Struc; % 'Data' is the property storing the structure
            elseif strcmp(app.SpikeDetectionapp.SorterDropDown.Value,"SpyKING CIRCUS 2")
                methodValues = Spike_Module_extractMethodValues(app.SpikeDetectionapp.ParameterStructure.SC2);

                
                [TempStruc] = Spike_Module_ConvertTextToParameterStruc(app.TextArea.Value,app.SpikeDetectionapp.SorterDropDown.Value,app.SpikeDetectionapp.ParameterStructure.SC2);
                Struc = Spike_Module_replaceMethodValues(TempStruc, methodValues);
                % Update the app's data structure
                app.SpikeDetectionapp.ParameterStructure.SC2 = Struc; % 'Data' is the property storing the structure
            elseif strcmp(app.SpikeDetectionapp.SorterDropDown.Value,"Kilosort 4")
                [Struc] = Spike_Module_ConvertTextToParameterStruc(app.TextArea.Value,app.SpikeDetectionapp.SorterDropDown.Value,app.SpikeDetectionapp.ParameterStructure.KS4);
                % Update the app's data structure
                app.SpikeDetectionapp.ParameterStructure.KS4 = Struc; % 'Data' is the property storing the structure
            end
        end

        % Button pushed function: RestoreDefaultButton
        function RestoreDefaultButtonPushed(app, event)
            app.OpenSpikeInterfaceGUIaftersortingCheckBox.Value = 1;
            app.PreprocessDataBandPassandWhiteningCheckBox.Value = 1;
            app.PlotTracesCheckBox.Value = 0;
            app.PlotsomesortingresultsCheckBox.Value = 0;
            app.LoadSpikeSortingCheckBox.Value = 0;
            app.LoopovermultipleRecordingsCheckBox.Value = 0;
            app.KeepConsoleOpen.Value = 0;

            [app.SpikeDetectionapp.ParameterStructure,app.TextArea.Value] = Spike_Module_ShowSpikeSortingSettings(app.SpikeDetectionapp.SorterDropDown.Value,app.SpikeDetectionapp.ParameterStructure,"ParameterNOTPresent");
            
        end

        % Button pushed function: SaveSettingsButton
        function SaveSettingsButtonPushed(app, event)
            % h = Ask_For_SaveName(app);
            % waitfor(h);
            TempFolderWithSettings = strcat(app.Mainapp.executableFolder,"\Modules\SpikeInterface\Saved_Spike_Sorting_Parameter\");

            [app.SaveName, filePath] = uiputfile(fullfile(TempFolderWithSettings,'*.mat'), 'Save as MAT file');

            % Check if the user selected a file (fileName will be 0 if canceled)
            if app.SaveName ~= 0
                if ~isempty(app.SaveName)
                    
                    FolderWithSettings = fullfile(filePath,app.SaveName);
                    
                    FolderWithSettings = convertStringsToChars(FolderWithSettings);
                    
                    app.SelectedSettings.OpenSpikeInterface = double(app.OpenSpikeInterfaceGUIaftersortingCheckBox.Value);
                    app.SelectedSettings.Preprocess = double(app.PreprocessDataBandPassandWhiteningCheckBox.Value);
                    app.SelectedSettings.PlotTraces = double(app.PlotTracesCheckBox.Value);
                    app.SelectedSettings.PlotSortingResults = double(app.PlotsomesortingresultsCheckBox.Value);           
                    app.SelectedSettings.Sorter = app.SpikeDetectionapp.SorterDropDown.Value;
                    app.SelectedSettings.MultipleRecordings = double(app.LoopovermultipleRecordingsCheckBox.Value);
                    app.SelectedSettings.LoadSorting = double(app.LoadSpikeSortingCheckBox.Value);
                    app.SelectedSettings.KeepConsoleOpen = double(app.KeepConsoleOpen.Value);

                    app.SpikeDetectionapp.SpikeInterfaceParameter = app.SelectedSettings;
    
                    if strcmp(app.SpikeDetectionapp.SorterDropDown.Value,"Mountainsort 5")
                        SortingParameterMS5 = app.SpikeDetectionapp.ParameterStructure.MS5;
                        GeneralParameterMS5 = app.SpikeDetectionapp.SpikeInterfaceParameter;
                        save(FolderWithSettings,'SortingParameterMS5','GeneralParameterMS5')
                    elseif strcmp(app.SpikeDetectionapp.SorterDropDown.Value,"SpyKING CIRCUS 2")
                        SortingParameterCS2 = app.SpikeDetectionapp.ParameterStructure.SC2;
                        GeneralParameterCS2 = app.SpikeDetectionapp.SpikeInterfaceParameter;
                        save(FolderWithSettings,'SortingParameterCS2','GeneralParameterCS2')
                    elseif strcmp(app.SpikeDetectionapp.SorterDropDown.Value,"Kilosort 4")
                        SortingParameterKS4 = app.SpikeDetectionapp.ParameterStructure.KS4;
                        GeneralParameterKS4 = app.SpikeDetectionapp.SpikeInterfaceParameter;
                        save(FolderWithSettings,'SortingParameterKS4','GeneralParameterKS4')
                    end
    
                    %% Show Saved Parameter
                    
                    [stringArray] = Utility_Extract_Contents_of_Folder(TempFolderWithSettings);
                    stringArray(stringArray=="") = [];
                    stringArray(stringArray=='') = [];
        
                    if ~isempty(stringArray)
                        for i = 1:length(stringArray)
                            app.LoadSavedParameterDropDown.Items{i} = convertStringsToChars(stringArray(i));
                        end
                    end
                end
            else

            end

        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            TempFolderWithSettings = strcat(app.Mainapp.executableFolder,"\Modules\SpikeInterface\Saved_Spike_Sorting_Parameter\");
            
            if ~isempty(app.LoadSavedParameterDropDown.Value)
                FileName = app.LoadSavedParameterDropDown.Value;
    
                FullFileToLoad = fullfile(TempFolderWithSettings,FileName);
                
                % List all variables with details
                fileDetails = whos('-file', FullFileToLoad);
                
                % Search for the variable in the details
                variableToCheck = 'SortingParameterMS5';
                isPresent = any(strcmp({fileDetails.name}, variableToCheck));
                
                if isPresent
                    load(FullFileToLoad,'SortingParameterMS5','GeneralParameterMS5')
                    app.SpikeDetectionapp.SorterDropDown.Value = "Mountainsort 5";
                    app.SpikeDetectionapp.SpikeInterfaceParameter = GeneralParameterMS5;
                    app.SpikeDetectionapp.ParameterStructure.MS5 = SortingParameterMS5;
                else
                    variableToCheck = 'SortingParameterCS2';
                    isPresent = any(strcmp({fileDetails.name}, variableToCheck));
                    if isPresent
                        app.SpikeDetectionapp.SorterDropDown.Value = "SpyKING CIRCUS 2";
                        load(FullFileToLoad,'SortingParameterCS2','GeneralParameterCS2')
                        app.SpikeDetectionapp.SpikeInterfaceParameter = GeneralParameterCS2;
                        app.SpikeDetectionapp.ParameterStructure.SC2 = SortingParameterCS2;
                    else
                        variableToCheck = 'SortingParameterKS4';
                        isPresent = any(strcmp({fileDetails.name}, variableToCheck));
                        if isPresent
                            app.SpikeDetectionapp.SorterDropDown.Value = "Kilosort 4";
                            load(FullFileToLoad,'SortingParameterKS4','GeneralParameterKS4')
                            app.SpikeDetectionapp.SpikeInterfaceParameter = GeneralParameterKS4;
                            app.SpikeDetectionapp.ParameterStructure.KS4 = SortingParameterKS4;
                        else
                            msgbox("Error: Selected .mat file does not contain expected parameter strcuture.")
                            return;
                        end
                    end
                end
    
                if ~isempty(app.SpikeDetectionapp.SpikeInterfaceParameter)
                    app.OpenSpikeInterfaceGUIaftersortingCheckBox.Value = app.SpikeDetectionapp.SpikeInterfaceParameter.OpenSpikeInterface;
                    app.PreprocessDataBandPassandWhiteningCheckBox.Value = app.SpikeDetectionapp.SpikeInterfaceParameter.Preprocess;
                    app.PlotTracesCheckBox.Value = app.SpikeDetectionapp.SpikeInterfaceParameter.PlotTraces;
                    app.PlotsomesortingresultsCheckBox.Value = app.SpikeDetectionapp.SpikeInterfaceParameter.PlotSortingResults;
                    app.LoadSpikeSortingCheckBox.Value = app.SpikeDetectionapp.SpikeInterfaceParameter.LoadSorting;
                    app.LoopovermultipleRecordingsCheckBox.Value = app.SpikeDetectionapp.SpikeInterfaceParameter.MultipleRecordings;
                    if isfield(app.SpikeDetectionapp.SpikeInterfaceParameter,'KeepConsoleOpen')
                        app.KeepConsoleOpen.Value = app.SpikeDetectionapp.SpikeInterfaceParameter.KeepConsoleOpen;
                    else
                        app.KeepConsoleOpen.Value = 0;
                    end
                else
                    app.OpenSpikeInterfaceGUIaftersortingCheckBox.Value = 1;
                    app.PreprocessDataBandPassandWhiteningCheckBox.Value = 1;
                    app.PlotTracesCheckBox.Value = 0;
                    app.PlotsomesortingresultsCheckBox.Value = 0;
                    app.LoadSpikeSortingCheckBox.Value = 0;
                    app.LoopovermultipleRecordingsCheckBox.Value = 0;
                    app.KeepConsoleOpen.Value = 0;
                end
    
                app.SorterMountainsort5Label.Text = strcat("Sorter: ",app.SpikeDetectionapp.SorterDropDown.Value);
    
                if isempty(app.SpikeDetectionapp.ParameterStructure)
                    [app.SpikeDetectionapp.ParameterStructure,app.TextArea.Value] = Spike_Module_ShowSpikeSortingSettings(app.SpikeDetectionapp.SorterDropDown.Value,app.SpikeDetectionapp.ParameterStructure,"ParameterNOTPresent");
                else
                    [app.SpikeDetectionapp.ParameterStructure,app.TextArea.Value] = Spike_Module_ShowSpikeSortingSettings(app.SpikeDetectionapp.SorterDropDown.Value,app.SpikeDetectionapp.ParameterStructure,"ParameterPresent");
                end
                
                %% Show Saved Parameter
                FolderWithSettings = strcat(app.Mainapp.executableFolder,"\Modules\SpikeInterface\Saved_Spike_Sorting_Parameter");
                [stringArray] = Utility_Extract_Contents_of_Folder(FolderWithSettings);
                stringArray(stringArray=="") = [];
                stringArray(stringArray=='') = [];
    
                if ~isempty(stringArray)
                    for i = 1:length(stringArray)
                        app.LoadSavedParameterDropDown.Items{i} = convertStringsToChars(stringArray(i));
                    end
                end

            else
                msgbox("No file found to load! Please save settings as a .mat file in the directory 'Path_to_GUI/Modules/SpikeInterface/Saved_Spike_Sorting_Parameter'!")
            end

        end

        % Value changed function: OpenSpikeInterfaceGUIaftersortingCheckBox
        function OpenSpikeInterfaceGUIaftersortingCheckBoxValueChanged(app, event)
            value = app.OpenSpikeInterfaceGUIaftersortingCheckBox.Value;
            if value == 1
                app.PlotTracesCheckBox.Enable = "off";
                app.PlotsomesortingresultsCheckBox.Enable = "off";
                app.PlotTracesCheckBox.Value = 0;
                app.PlotsomesortingresultsCheckBox.Value = 0;
            else
                app.PlotTracesCheckBox.Enable = "on";
                app.PlotsomesortingresultsCheckBox.Enable = "on";
            end
        end

        % Value changed function: KeepConsoleOpen
        function KeepConsoleOpenValueChanged(app, event)
            value = app.KeepConsoleOpen.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpikeInterfaceParameterSelectionUIFigure = uifigure('Visible', 'off');
            app.SpikeInterfaceParameterSelectionUIFigure.Name = 'SpikeInterface Parameter Selection';
            app.SpikeInterfaceParameterSelectionUIFigure.Icon = 'Logo.png';
            app.GeneralSettingsPanel = uipanel(app.SpikeInterfaceParameterSelectionUIFigure);
            app.GeneralSettingsPanel.Title = 'General Settings';
            app.OpenSpikeInterfaceGUIaftersortingCheckBox = uicheckbox(app.GeneralSettingsPanel);
            app.OpenSpikeInterfaceGUIaftersortingCheckBox.ValueChangedFcn = createCallbackFcn(app, @OpenSpikeInterfaceGUIaftersortingCheckBoxValueChanged, true);
            app.OpenSpikeInterfaceGUIaftersortingCheckBox.Text = {'   Open SpikeInterface GUI '; '   after sorting'};
            app.OpenSpikeInterfaceGUIaftersortingCheckBox.Value = true;
            app.PlotsomesortingresultsCheckBox = uicheckbox(app.GeneralSettingsPanel);
            app.PlotsomesortingresultsCheckBox.Enable = 'off';
            app.PlotsomesortingresultsCheckBox.Text = '   Plot Some Sorting Results ';
            app.PlotTracesCheckBox = uicheckbox(app.GeneralSettingsPanel);
            app.PlotTracesCheckBox.Enable = 'off';
            app.PlotTracesCheckBox.Text = '   Plot Traces and Probe';
            app.LoadSpikeSortingCheckBox = uicheckbox(app.GeneralSettingsPanel);
            app.LoadSpikeSortingCheckBox.Text = '   Load Spike Sorting';
            app.SorterMountainsort5Label = uilabel(app.GeneralSettingsPanel);
            app.SorterMountainsort5Label.Text = 'Sorter: Mountainsort 5';
            app.LoadSavedParameterDropDownLabel = uilabel(app.GeneralSettingsPanel);
            app.LoadSavedParameterDropDownLabel.Text = {'Load Saved '; 'Parameter'};
            app.LoadSavedParameterDropDown = uidropdown(app.GeneralSettingsPanel);
            app.LoadSavedParameterDropDown.Items = {''};
            app.LoadSavedParameterDropDown.Value = '';
            app.LoadButton = uibutton(app.GeneralSettingsPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.KeepConsoleOpen = uicheckbox(app.GeneralSettingsPanel);
            app.KeepConsoleOpen.ValueChangedFcn = createCallbackFcn(app, @KeepConsoleOpenValueChanged, true);
            app.KeepConsoleOpen.Text = {'   Keep Python Console Open'; '   when Finishied (requires to '; '   press enter in the console and Matlab)'};
            app.PreprocessDataBandPassandWhiteningCheckBox = uicheckbox(app.GeneralSettingsPanel);
            app.PreprocessDataBandPassandWhiteningCheckBox.Text = {'   Preprocess Data before Sorting'; '   (Band-Pass and Whitening)'};
            app.PreprocessDataBandPassandWhiteningCheckBox.Value = true;
            app.LoopovermultipleRecordingsCheckBox = uicheckbox(app.GeneralSettingsPanel);
            app.LoopovermultipleRecordingsCheckBox.Text = '   Loop over Multiple Recordings';
            app.GeneralSettingsLabel = uilabel(app.GeneralSettingsPanel);
            app.GeneralSettingsLabel.Text = 'General Settings';
            app.SetNewSettingsandContinueButton = uibutton(app.SpikeInterfaceParameterSelectionUIFigure, 'push');
            app.SetNewSettingsandContinueButton.ButtonPushedFcn = createCallbackFcn(app, @SetNewSettingsandContinueButtonPushed, true);
            app.SetNewSettingsandContinueButton.Text = {'Set New Settings '; 'and Continue'};
            app.CloseButton = uibutton(app.SpikeInterfaceParameterSelectionUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.TextArea = uitextarea(app.SpikeInterfaceParameterSelectionUIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.SortingParameterLabel = uilabel(app.SpikeInterfaceParameterSelectionUIFigure);
            app.SortingParameterLabel.Text = 'Sorting Parameter';
            app.RestoreDefaultButton = uibutton(app.SpikeInterfaceParameterSelectionUIFigure, 'push');
            app.RestoreDefaultButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreDefaultButtonPushed, true);
            app.RestoreDefaultButton.Text = 'Restore Default';
            app.SaveSettingsButton = uibutton(app.SpikeInterfaceParameterSelectionUIFigure, 'push');
            app.SaveSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSettingsButtonPushed, true);
            app.SaveSettingsButton.Text = 'Save Settings';
            app.ModifyValuesaftertheLabel = uilabel(app.SpikeInterfaceParameterSelectionUIFigure);
            app.ModifyValuesaftertheLabel.Text = 'Modify Values after the '':''';
            % Show the figure after all components are created
            app.SpikeInterfaceParameterSelectionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpikeInterface_SpikeSorting_Parameter(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SpikeInterfaceParameterSelectionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpikeInterfaceParameterSelectionUIFigure)
        end
    end
end