classdef Example_App_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ExampleApp1UIFigure   matlab.ui.Figure
        CloseButton           matlab.ui.control.Button
        NeuromodToolboxLabel  matlab.ui.control.Label
        Image                 matlab.ui.control.Image
        ExampleApp1Label      matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Mainapp = []; % Access Main app window data structure everywhere
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            
            % Moce this window in the middle of thescreen
            movegui(app.ExampleApp1UIFigure, 'center');
            
            % save the main app window as property of this app window to access
            % data everywhere
            app.Mainapp = Mainapp;

            %% Now you can access the whole dataset!
            % Raw Data: app.Mainapp.Data.Raw
            % Preprocessed Data: app.Mainapp.Data.Preprocessed
            % Events: app.Mainapp.Data.Events
            % and so on. See documentation for whole datastructure!

            % Changes to the dataset in this window automatically apply to
            % the dataset other windows have access to!

            %% Example: Plot part of raw data
            plot(app.UIAxes,app.Mainapp.Data.Time(1:10000),app.Mainapp.Data.Raw(1,1:10000));

            %% If you changed the raw and/or preprocessed dataset and want to plot it in the main app window data plot use the following function:
            
            %Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
    
            %% If you changed the app.Mainapp.Data.Info structure holding the recording information and want to show the updated structure in the main window:
            
            %Utility_Show_Info_Loaded_Data(app.Mainapp);

            %% How to set up functions:
            % 1. On startup: GUI exclusive function setting up all dropwdown
            % menus and so on based on the current datast.
            % 2. Preparation function: Check user inputs and select dataset
            % to plot/analyse based on it. No app object as input to be able to use it outside the GUI 
            % 3. Analysis/Plot function. No app object as input to be able to use it outside the GUI

            %i.e.:
            %[PlotInfo,DatatoPlot] = Preparefunction(app.Mainapp.Data,app.Channelselection.Value,app.EventSelection.Value); % plotinfo can for example hold channelselection to show in plot title
            %Plotfunction(app.UIAxes,PlotInfo,DatatoPlot);% pass figure objects you want to plot in.

            %% To check numeric inputs the user has to give:
            %[ChannelEditField.Value] = Utility_SimpleCheckInputs(ChannelEditField.Value,"Two",strcat('1,',num2str(size(Data.Raw,1))),1,0);

        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ExampleApp1UIFigure = uifigure('Visible', 'off');
            app.ExampleApp1UIFigure.Name = 'Example App 1';
            app.ExampleApp1UIFigure.Icon = 'Logo.png';
            app.UIAxes = uiaxes(app.ExampleApp1UIFigure);
            title(app.UIAxes, 'Example Plot Raw Data Channel 1')
            xlabel(app.UIAxes, 'Time [s]')
            ylabel(app.UIAxes, 'Signal [mv]')
            zlabel(app.UIAxes, 'Z')
            app.ExampleApp1Label = uilabel(app.ExampleApp1UIFigure);
            app.ExampleApp1Label.Text = 'Example App 1';
            app.Image = uiimage(app.ExampleApp1UIFigure);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodToolboxLabel = uilabel(app.ExampleApp1UIFigure);
            app.NeuromodToolboxLabel.Text = 'Neuromod Toolbox';
            app.CloseButton = uibutton(app.ExampleApp1UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.ExampleApp1UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Example_App_1(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ExampleApp1UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ExampleApp1UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ExampleApp1UIFigure)
        end
    end
end