classdef Neuromod_Toolbox_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NeuromodToolboxMainWindowUIFigure  matlab.ui.Figure
        ViewMenu                        matlab.ui.container.Menu
        ChangePlotPropertiesMenu        matlab.ui.container.Menu
        DataPlotMenu                    matlab.ui.container.Menu
        PlotTypeMenu                    matlab.ui.container.Menu
        IndividualChannelLinesMenu      matlab.ui.container.Menu
        ImagescMenu                     matlab.ui.container.Menu
        GridTraceViewMenu               matlab.ui.container.Menu
        GridActivityViewMenu            matlab.ui.container.Menu
        Show2DGridActivityMenu          matlab.ui.container.Menu
        Show3DGridActivityMenu          matlab.ui.container.Menu
        GridAxonViewerMenu              matlab.ui.container.Menu
        ShowAxonViewerMenu              matlab.ui.container.Menu
        SpikeSelectorMenu               matlab.ui.container.Menu
        SpikeDetectionThresholdMenu     matlab.ui.container.Menu
        VisualizePathTrailsMenu         matlab.ui.container.Menu
        ONMenu_2                        matlab.ui.container.Menu
        OFFMenu_2                       matlab.ui.container.Menu
        NumberofPastFramesConsideredMenu_2  matlab.ui.container.Menu
        SetAllValuesBelowThresholdto1Menu  matlab.ui.container.Menu
        ONMenu_3                        matlab.ui.container.Menu
        OFFMenu_3                       matlab.ui.container.Menu
        ClimSettingsMenu                matlab.ui.container.Menu
        ActivateLockedClimMenu          matlab.ui.container.Menu
        DeactivateLockedClimMenu        matlab.ui.container.Menu
        ChangeGridSettingsMenu          matlab.ui.container.Menu
        ChangeSpatialResolutionMenu_2   matlab.ui.container.Menu
        ChangeTimeResulotionMenu_2      matlab.ui.container.Menu
        ColormapMenu                    matlab.ui.container.Menu
        parulaMenu                      matlab.ui.container.Menu
        turboMenu                       matlab.ui.container.Menu
        hsvMenu                         matlab.ui.container.Menu
        hotMenu                         matlab.ui.container.Menu
        coolMenu                        matlab.ui.container.Menu
        springMenu                      matlab.ui.container.Menu
        summerMenu                      matlab.ui.container.Menu
        autumnMenu                      matlab.ui.container.Menu
        grayMenu                        matlab.ui.container.Menu
        boneMenu                        matlab.ui.container.Menu
        FontSizeMenu                    matlab.ui.container.Menu
        LineWidthDataMenu               matlab.ui.container.Menu
        LineWidthSpikesMenu             matlab.ui.container.Menu
        LineWidthEventsMenu             matlab.ui.container.Menu
        ShowTriggerNumberMenu           matlab.ui.container.Menu
        ONMenu                          matlab.ui.container.Menu
        OFFMenu                         matlab.ui.container.Menu
        TrigerNumberTextFontsizeMenu    matlab.ui.container.Menu
        TrigerNumberTextColorMenu       matlab.ui.container.Menu
        ColorSpikesMenu                 matlab.ui.container.Menu
        ColorEventsMenu                 matlab.ui.container.Menu
        BackgroundColorMenu_2           matlab.ui.container.Menu
        XLabelMenu                      matlab.ui.container.Menu
        YLabelMenu                      matlab.ui.container.Menu
        RESETMenu                       matlab.ui.container.Menu
        TimePlotMenu                    matlab.ui.container.Menu
        TitleMenu_2                     matlab.ui.container.Menu
        FontSizeMenu_2                  matlab.ui.container.Menu
        ColorTimeRectangleMenu          matlab.ui.container.Menu
        ColorEventsMenu_2               matlab.ui.container.Menu
        LineWidthTimeRectangleMenu      matlab.ui.container.Menu
        LineWidthEventsMenu_2           matlab.ui.container.Menu
        BackgroundColorMenu             matlab.ui.container.Menu
        XLabelMenu_2                    matlab.ui.container.Menu
        YLabelMenu_2                    matlab.ui.container.Menu
        RESETMenu_2                     matlab.ui.container.Menu
        SpikePlotAppearanceMenu         matlab.ui.container.Menu
        PlotasPointsMenu                matlab.ui.container.Menu
        PlotasWaveformsMenu             matlab.ui.container.Menu
        SaveCostumeAppearanceMenu       matlab.ui.container.Menu
        DeleteCostumeAppearanceMenu     matlab.ui.container.Menu
        ChangeColorschemeMenu           matlab.ui.container.Menu
        DarkModeDLMenu                  matlab.ui.container.Menu
        DarkModeLDMenu                  matlab.ui.container.Menu
        LightModeDLMenu                 matlab.ui.container.Menu
        LightModeLDMenu                 matlab.ui.container.Menu
        SetcurrentschemeasnewDefaultMenu  matlab.ui.container.Menu
        FullscreenMenu                  matlab.ui.container.Menu
        WindowedMenu                    matlab.ui.container.Menu
        SnapshotMenu                    matlab.ui.container.Menu
        SaveSnapshotofMainWindowMenu    matlab.ui.container.Menu
        PNGMenu                         matlab.ui.container.Menu
        JPGMenu                         matlab.ui.container.Menu
        SVGMenu                         matlab.ui.container.Menu
        ManageDatasetMenu               matlab.ui.container.Menu
        ViewExportorDeleteDataComponentsMenu  matlab.ui.container.Menu
        DeleteEventTriggerIndicesMenu   matlab.ui.container.Menu
        AutorunAnalysisMenu             matlab.ui.container.Menu
        AutorunManagerWindowMenu        matlab.ui.container.Menu
        ManageModulesMenu               matlab.ui.container.Menu
        SwitchAddModulesMenu            matlab.ui.container.Menu
        ExtrasMenu                      matlab.ui.container.Menu
        ControlsMenu_2                  matlab.ui.container.Menu
        HelpMenu_2                      matlab.ui.container.Menu
        GithubMenu                      matlab.ui.container.Menu
        ManualMenu                      matlab.ui.container.Menu
        TooltipsMenu_2                  matlab.ui.container.Menu
        TurnOnMenu_2                    matlab.ui.container.Menu
        TurnOffMenu_2                   matlab.ui.container.Menu
        SaveasnewDefaultMenu_2          matlab.ui.container.Menu
        ResetPlotMenu                   matlab.ui.container.Menu
        DeleteSavedPathsMenu            matlab.ui.container.Menu
        PythonPathSpikeInterfaceEnvironmentMenu  matlab.ui.container.Menu
        PythonPathNEOEnvironmentMenu    matlab.ui.container.Menu
        PythonPathPhyEnvironmentMenu    matlab.ui.container.Menu
        Spike2CEDS64MLinterfacelibraryMenu  matlab.ui.container.Menu
        Grid_Traces_View_Panel          matlab.ui.container.Panel
        Button_5                        matlab.ui.control.Button
        MainPlotAnalysisModuleLabel     matlab.ui.control.Label
        ManageDatasetLabel              matlab.ui.control.Label
        MainPlotAnalysisModulePanel     matlab.ui.container.Panel
        RUNButton_5                     matlab.ui.control.Button
        ListBox_6                       matlab.ui.control.ListBox
        ContinousDataModulePanel        matlab.ui.container.Panel
        ContinousDataModuleLabel        matlab.ui.control.Label
        RUNButton_3                     matlab.ui.control.Button
        ListBox_3                       matlab.ui.control.ListBox
        ManageDatasetPanel              matlab.ui.container.Panel
        RUNButton_4                     matlab.ui.control.Button
        ListBox_5                       matlab.ui.control.ListBox
        EventDataModulePanel            matlab.ui.container.Panel
        EventDataTTLModuleLabel         matlab.ui.control.Label
        ListBox_2                       matlab.ui.control.ListBox
        RUNButton_2                     matlab.ui.control.Button
        SpikeModulePanel_2              matlab.ui.container.Panel
        SpikeModuleLabel                matlab.ui.control.Label
        RUNButton                       matlab.ui.control.Button
        ListBox                         matlab.ui.control.ListBox
        RecordingandDatasetInformationLabel  matlab.ui.control.Label
        NeuroModLabel                   matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        MainWindowLabel                 matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        TimeSpanandAxisControlPanel     matlab.ui.container.Panel
        TimeSpanandAxisControlLabel     matlab.ui.control.Label
        IncreaseDecreaseTimeLabel       matlab.ui.control.Label
        PlaybackLabel                   matlab.ui.control.Label
        ForwardLabel                    matlab.ui.control.Label
        BackwardLabel                   matlab.ui.control.Label
        TimetoManipulateMainPlotLabel   matlab.ui.control.Label
        TimeSpanControlDropDown         matlab.ui.control.DropDown
        PauseButton                     matlab.ui.control.Button
        PlayButton                      matlab.ui.control.Button
        Button_3                        matlab.ui.control.Button
        Button_4                        matlab.ui.control.Button
        TimeRangeViewBox                matlab.ui.control.EditField
        CurrentTimeSpanLabel            matlab.ui.control.Label
        Button_2                        matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        ChannelandPlotControlPanel      matlab.ui.container.Panel
        EventTriggerNumberField         matlab.ui.control.EditField
        ChannelandPlotControlLabel      matlab.ui.control.Label
        DropDown_2                      matlab.ui.control.DropDown
        OpenProbeViewButton             matlab.ui.control.Button
        DropDown                        matlab.ui.control.DropDown
        EventChannelDropDown            matlab.ui.control.DropDown
        EventSelectionLabel             matlab.ui.control.Label
        PlotSelectionLabel              matlab.ui.control.Label
        DataLineSpacingLabel            matlab.ui.control.Label
        Slider                          matlab.ui.control.Slider
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        ContextMenu4                    matlab.ui.container.ContextMenu
        ChangeDataLineSpacingRangeMenu  matlab.ui.container.Menu
        ContextMenu5                    matlab.ui.container.ContextMenu
        ChangeNumberofSamplestoSkipMenu  matlab.ui.container.Menu
        AddAdditionalDelayMenu          matlab.ui.container.Menu
    end

    %% Define shared variables in app object
    properties (Access = public)
        executableFolder = []; %% Saves path to GUI
        Data = []; % Structure holding Data
        PlotInfo = [];
        ChannelTextHandle = []; % Handle that saves the channel nr the user clicked on in the data plot
        ChannelSelection = []; % Variable saving Channels Selected in Main window
        DefaultTimeRangeDataPlot = []; % Hold time range currently selected to plot data (in amounts of samples)
        TimeLimit = []; % Min and Max number of samples allowed to be showed in plot
        CurrentTimePoints = []; % Current number of settings selected in the time plot and viewed in the data plot
        CheckSuccesful_Extraction = []; % Variable that saves a 1 if data extraction was succesful, 0 if not. This variable is set in the data extraction module GUI
        CheckPlayMovie = [];
        rectangleHandle = []; % Save handle of rectangle in time plot to update fast
        PlotComponents = [] % Captures what the user wants to display in the plot
        ManuallyChangedPlotSpeed = 0;
        BandPowerResults = [];
        FlagPlotDownsampledData = 0;
        FlagPlotRawData = 1;
        ClimMaxValues = [];
        AdditionalPlotDelay = 0;

        PreviousThreshGrids = [];
        PlotThreshGrids = [];
        
        PlayedMovieBefore = 0;
         
        ProbeViewProperties = []; % dimension of plotted channel in probe to overlay spike positions (for proper scaling)

        PlotEvents = "no"; % for main window plot
        Plotspikes = "no"; % for main window plot
        TwoORThreeD = "TwoD"; % standard 2d plots when possible

        PlotLineSpacing = []; % Info from slider how much channel should be apart
        Channelrange = []; % Info from GUI which channel are selected
        tempcolorMapset = []; % Holding result Eval function integrating GUI selection. This is used for the plot functions!
        tempcolorMap = "parula";
        CurrentEventChannel = [];

        LastPlot = [];
        PowerSpecResults = [];

        PlotAppearance = [];
        SpikePlotType = "Points";

        CurrentPlotData = [];

        CurrentModules = [];

        ChannelChange = "ProbeView"; % OR ProbeView to capture where channelselection was changed
        ActiveChannel = [];

        MovieFramesPerSecond = 40;
        MovieTimeToJump = 1; % in samples

        Colorscheme = 'DarkMode_Dark_Light'

        PreservePlotChannelLocations = 1;
        IsFullScreen = 0;
        
        %% Handles for Toolbox windows
        ExtractDataWindow = [];
        ProbeViewWindowHandle = [];

        % for uipanel
        ChannelGrid = [];
        ChannelAxes = [];

        LoadDataWindow = [];
        SaveDataWindow = [];

        SpikeExtractionWindow = [];
        LoadfromKilosortWindowWindow = []
        SaveforKilosortWindowWindow = [];
        
        PreproWindow = [];
        ContStaticSpectrumWindow = [];

        PSTHApp = [];
        CSDApp = [];
        SpectralEstApp = [];
        LiveSpectrogramApp = [];

        ConInternalSpikesWindow = [];
        ConKilosortSpikesWindow = [];
        UnitAnalysis = [];

        EventExtractionWindow = [];
        ImportEventTTLWindow = [];
        EventInternalSpikesWindow = [];
        EventKilosortSpikesWindow = [];

        LFPEventsMainWindow = [];
        EventLFPERP = [];
        EventLFPCSD = [];
        EventLFPTF = [];
        EventLFPSSP = [];
        EventPhaseSynchro = [];

        Manage_Dataset_ComponentsWindow = []

        PreproArtefactRejection = [];
        PreproEventsMainWindow = [];

        ShowToolTipsSetting = [];

        ManageModulesWindow = [];
        AutorunWindow = [];

        LiveECHTWindow = [];

        EventIndiceRejectionWindow = [];
        FieldTripLFPWindow = [];

        SpikeSelectorWindow = [];
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %%% Put GUI in the center of the window (with adaptive screen resolution)    
            movegui(app.NeuromodToolboxMainWindowUIFigure, 'center');
            % Check Screen resolution and scale to know whether sm has to
            % change in scale

            %% Get Path from which the current GUI object is executed
            if isdeployed % if standalone
                app.executableFolder = pwd;
                %app.executableFolder = ctfroot;
            else
                % User is running an m-file from the MATLAB integrated development environment (regular MATLAB).
                tmp_path = mfilename('fullpath');
                [app.executableFolder, ~, ~] = fileparts(tmp_path);
            end

            app.CurrentTimePoints = uint64(app.CurrentTimePoints);
            %%% Set the Path to the GUI
            
            %% Add Path to Matlab automatically
            folder = fileparts(which(mfilename)); 
            if ~(ismcc || isdeployed)
                % Add that folder plus all subfolders to the path.
                addpath(genpath(folder));
            end

            %% Initiate Modules

            [~,app] = All_Module_Items(app,1,[]);

            %% Load Standard ToolTip Setting
            FileToLoad = strcat(app.executableFolder,"\Modules\MISC\Variables (do not edit)\ShowToolTipsSetting.mat");
            if isfile(FileToLoad)
                load(FileToLoad,'ShowToolTips');
                app.ShowToolTipsSetting = ShowToolTips;
                if ShowToolTips == 1
                    disp("Standard ToolTip Setting is set to ON and tooltips will be shown.")
                else
                    disp("Standard ToolTip Setting is set to OFF and tooltips will not be shown.")
                end
            else
                ShowToolTips = 1;
                save(FileToLoad,'ShowToolTips')
                app.ShowToolTipsSetting = ShowToolTips;

                disp("Standard ToolTip Setting is set to ON and tooltips will be shown.")
            end

            %% Load ColorScheme Setting
            FileToLoad = strcat(app.executableFolder,"\Modules\MISC\Variables (do not edit)\SavedColorScheme.mat");
            if isfile(FileToLoad)
                load(FileToLoad,'ColorScheme');
                app.Colorscheme = ColorScheme;
                disp(strcat("Colorscheme set to ",app.Colorscheme))
            else
                ColorScheme = app.Colorscheme;
                save(FileToLoad,'ColorScheme')

                disp("Standard colorscheme is set to Dark Mode D/L.")
            end

            [app] = Utility_Set_ToolTips(app,app.ShowToolTipsSetting,"MainWindow");
            
            app = Utility_Change_Light_Dark_Mode(app,"MainWindow");

            Organize_Check_Path_Variables(app.executableFolder);

        end

        % Callback function
        function ButtonPushed(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                f = msgbox("Warning! No Data was extracted.");
                return;
            end

            Organize_Set_MainWindow_TimeRange(app,str2double(app.TimeRangeViewBox.Value(1:end-1)),[0.001,app.Data.Time(end)],"Plus")

            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Subsequent","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);
            
        end

        % Callback function
        function Button_2Pushed(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                f = msgbox("Warning! No Data was extracted.");
                return;
            end

            Organize_Set_MainWindow_TimeRange(app,str2double(app.TimeRangeViewBox.Value(1:end-1)),[0.001,app.Data.Time(end)],"Minus")

            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Subsequent","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);
        end

        % Callback function
        function Button_3Pushed(app, event)
         
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                f = msgbox("Warning! No Data was extracted.");
                return;
            end

            %% Determine new starting time point of data plot
           
            if isfield(app.Data.Info,'DownsampleFactor') && strcmp(app.DropDown.Value,'Preprocessed Data')
                [app] = Organize_Jump_in_Time(app,"Forward",numel(app.Data.TimeDownsampled),str2double(app.TimeRangeViewBox.Value(1:end-1))*app.Data.Info.DownsampledSampleRate,app.Data.Info.DownsampledSampleRate);
            else
                [app] = Organize_Jump_in_Time(app,"Forward",numel(app.Data.Time),str2double(app.TimeRangeViewBox.Value(1:end-1))*app.Data.Info.NativeSamplingRate,app.Data.Info.NativeSamplingRate);
            end
            
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Subsequent","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);

         
        end

        % Callback function
        function Button_4Pushed(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                f = msgbox("Warning! No Data was extracted.");
                return;
            end

            %% Determine new starting time point of data plot

            if isfield(app.Data.Info,'DownsampleFactor') && strcmp(app.DropDown.Value,'Preprocessed Data')
                [app] = Organize_Jump_in_Time(app,"Backwards",numel(app.Data.TimeDownsampled),str2double(app.TimeRangeViewBox.Value(1:end-1)),app.Data.Info.DownsampledSampleRate);
            else
                [app] = Organize_Jump_in_Time(app,"Backwards",numel(app.Data.Time),str2double(app.TimeRangeViewBox.Value(1:end-1)),app.Data.Info.NativeSamplingRate);
            end

            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Subsequent","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);

        end

        % Callback function
        function PlayButtonPushed(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                f = msgbox("Warning! No Data was extracted.");
                return;
            end

            app.CheckPlayMovie = 1;
            
            if isfield(app.Data.Info,'DownsampleFactor') && strcmp(app.DropDown.Value,'Preprocessed Data')
                TimeRangeofPlot = str2double(app.TimeRangeViewBox.Value(1:end-1))*app.Data.Info.DownsampledSampleRate;
                Maxtime = numel(app.Data.TimeDownsampled);
            else
                TimeRangeofPlot = str2double(app.TimeRangeViewBox.Value(1:end-1))*app.Data.Info.NativeSamplingRate;
                Maxtime = numel(app.Data.Time);
            end
                        
            while(app.CheckPlayMovie == 1)
                tic
                if app.CurrentTimePoints <= Maxtime-TimeRangeofPlot
                    %pause(0.0001);
                    %Add number of samples corresponding to time increase
                    %selected in the checkbox to the current index at which
                    %data is shown
                    
                    if isfield(app.Data.Info,'DownsampleFactor') && strcmp(app.DropDown.Value,'Preprocessed Data')
                        app.CurrentTimePoints = app.CurrentTimePoints + round((app.MovieTimeToJump*app.Data.Info.DownsampledSampleRate));
                    else
                        app.CurrentTimePoints = app.CurrentTimePoints + round((app.MovieTimeToJump*app.Data.Info.NativeSamplingRate));
                    end
    
                    %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                    % Plot functions are fully autonomous without needed the app
                    % object. It is only needed to get the necessary parameter.
                    % Both is combined in one function for convenience.
                    %input 2: 1 if plot time, 0 if no time plot necessary
                    %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                    %input 4: Whether Data plot should run in a movie or not
                    Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Subsequent","MainWindowTimeManipulationMovie",app.PlotEvents,app.Plotspikes);
                else
                    app.CheckPlayMovie = 0;
                end
                toc
            end
        end

        % Callback function
        function PauseButtonPushed(app, event)
            app.CheckPlayMovie = 0;
        end

        % Menu selected function: parulaMenu
        function parulaMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end

            app.tempcolorMap = "parula";
            app.tempcolorMapset = eval(strcat(app.tempcolorMap,"(size(app.Data.Raw,1))")); % Example colormap: You can use any other colormap

            colormap(app.UIAxes,"parula");
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,0,"Initial","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: turboMenu
        function turboMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                f = msgbox("Warning! No Data was extracted.");
                return;
            end            
            app.tempcolorMap = "turbo";
            app.tempcolorMapset = eval(strcat(app.tempcolorMap,"(size(app.Data.Raw,1))")); % Example colormap: You can use any other colormap

            colormap(app.UIAxes,"turbo");
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,0,"Initial","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: hsvMenu
        function hsvMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                f = msgbox("Warning! No Data was extracted.");
                return;
            end     
            app.tempcolorMap = "hsv";
            app.tempcolorMapset = eval(strcat(app.tempcolorMap,"(size(app.Data.Raw,1))")); % Example colormap: You can use any other colormap

            colormap(app.UIAxes,"hsv");
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                Organize_Prepare_Plot_and_Extract_GUI_Info(app,0,"Initial","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: hotMenu
        function hotMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                f = msgbox("Warning! No Data was extracted.");
                return;
            end     
            app.tempcolorMap = "hot";
            app.tempcolorMapset = eval(strcat(app.tempcolorMap,"(size(app.Data.Raw,1))")); % Example colormap: You can use any other colormap

            colormap(app.UIAxes,"hot");
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                Organize_Prepare_Plot_and_Extract_GUI_Info(app,0,"Initial","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: coolMenu
        function coolMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                f = msgbox("Warning! No Data was extracted.");
                return;
            end     
            app.tempcolorMap = "cool";
            app.tempcolorMapset = eval(strcat(app.tempcolorMap,"(size(app.Data.Raw,1))")); % Example colormap: You can use any other colormap

            colormap(app.UIAxes,"cool");

            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                Organize_Prepare_Plot_and_Extract_GUI_Info(app,0,"Initial","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: springMenu
        function springMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                f = msgbox("Warning! No Data was extracted.");
                return;
            end     
            app.tempcolorMap = "spring";
            app.tempcolorMapset = eval(strcat(app.tempcolorMap,"(size(app.Data.Raw,1))")); % Example colormap: You can use any other colormap

            colormap(app.UIAxes,"spring");

            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                Organize_Prepare_Plot_and_Extract_GUI_Info(app,0,"Initial","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: summerMenu
        function summerMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                f = msgbox("Warning! No Data was extracted.");
                return;
            end     

            app.tempcolorMap = "summer";
            app.tempcolorMapset = eval(strcat(app.tempcolorMap,"(size(app.Data.Raw,1))")); % Example colormap: You can use any other colormap

            colormap(app.UIAxes,"summer");

            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                Organize_Prepare_Plot_and_Extract_GUI_Info(app,0,"Initial","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: autumnMenu
        function autumnMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                f = msgbox("Warning! No Data was extracted.");
                return;
            end     
            app.tempcolorMap = "autumn";
            app.tempcolorMapset = eval(strcat(app.tempcolorMap,"(size(app.Data.Raw,1))")); % Example colormap: You can use any other colormap

            colormap(app.UIAxes,"autumn");
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                Organize_Prepare_Plot_and_Extract_GUI_Info(app,0,"Initial","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: grayMenu
        function grayMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                f = msgbox("Warning! No Data was extracted.");
                return;
            end     
            app.tempcolorMap = "gray";
            app.tempcolorMapset = eval(strcat(app.tempcolorMap,"(size(app.Data.Raw,1))")); % Example colormap: You can use any other colormap

            colormap(app.UIAxes,"gray");

            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                Organize_Prepare_Plot_and_Extract_GUI_Info(app,0,"Initial","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: boneMenu
        function boneMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                f = msgbox("Warning! No Data was extracted.");
                return;
            end     
            app.tempcolorMap = "bone";
            app.tempcolorMapset = eval(strcat(app.tempcolorMap,"(size(app.Data.Raw,1))")); % Example colormap: You can use any other colormap

            colormap(app.UIAxes,"bone");

            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                Organize_Prepare_Plot_and_Extract_GUI_Info(app,0,"Initial","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);
        end

        % Callback function
        function EventChannelDropDownValueChanged(app, event)
            value = app.EventChannelDropDown.Value;
            
            for i = 1:length(app.Data.Info.EventChannelNames)
                if strcmp(app.Data.Info.EventChannelNames{i},value)
                    app.CurrentEventChannel = i;
                end
            end
            
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Initial","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: FullscreenMenu
        function FullscreenMenuSelected(app, event)
            if strcmp(app.NeuromodToolboxMainWindowUIFigure.WindowState, 'normal')
                % Switch to fullscreen
                app.NeuromodToolboxMainWindowUIFigure.WindowState = "fullscreen";
                %set(app, 'WindowState', 'fullscreen');
                try
                    Utility_SetAppFontSize(app, 15);
                catch

                end
             
            end
        end

        % Menu selected function: WindowedMenu
        function WindowedMenuSelected(app, event)
            if strcmp(app.NeuromodToolboxMainWindowUIFigure.WindowState, 'fullscreen')
                % Switch to fullscreen
                app.NeuromodToolboxMainWindowUIFigure.WindowState = "normal";
                try
                    Utility_SetAppFontSize(app, 13);
                catch

                end
            end
        end

        % Close request function: NeuromodToolboxMainWindowUIFigure
        function NeuromodToolboxMainWindowUIFigureCloseRequest(app, event)
            try
                Organize_Delete_All_Open_Windows(app,1);
            end
            
            delete(app)
           
        end

        % Button pushed function: RUNButton_4
        function RUNButton_4Pushed(app, event)
            
            if strcmp(app.ListBox_5.Value,"Load Raw Recordings")

                %% Initiate basic GUI stuff when data is loaded
                
                %% Open Loading Data Module
                % Data and recording info are saved in app.Data structure
                app.ExtractDataWindow = Extract_Data_Window(app);

                [app] = Utility_Set_ToolTips(app,app.ShowToolTipsSetting,"ExtractDataWindow");


            elseif strcmp(app.ListBox_5.Value,"Load Dataset")

                %% Function to Load GUI Data (.mat files)
                
                app.LoadDataWindow = Load_Data_Window(app);

                [~] = Utility_Set_ToolTips(app,app.ShowToolTipsSetting,"LoadDataWindow");

            elseif strcmp(app.ListBox_5.Value,"Save Dataset")

                if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                    msgbox("Warning! No Data was extracted.");
                    return;
                end

                app.SaveDataWindow = Save_Data_Window(app);

                [~] = Utility_Set_ToolTips(app,app.ShowToolTipsSetting,"SaveDataWindow");
            end
        end

        % Button pushed function: RUNButton_3
        function RUNButton_3Pushed(app, event)
            for i = 1:length(app.CurrentModules.Functions)
                if strcmp(app.ContinousDataModulePanel.Title,app.CurrentModules.Title{i})
                    eval(strcat(app.CurrentModules.Functions{i},"(app,app.ListBox_3.Value);"));
                end
            end
        end

        % Button pushed function: RUNButton_2
        function RUNButton_2Pushed(app, event)
            for i = 1:length(app.CurrentModules.Functions)
                if strcmp(app.EventDataModulePanel.Title,app.CurrentModules.Title{i})
                    eval(strcat(app.CurrentModules.Functions{i},"(app,app.ListBox_2.Value);"));
                end
            end   
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            for i = 1:length(app.CurrentModules.Functions)
                if strcmp(app.SpikeModulePanel_2.Title,app.CurrentModules.Title{i})
                    eval(strcat(app.CurrentModules.Functions{i},"(app,app.ListBox.Value);"));
                end
            end   
        end

        % Menu selected function: SaveSnapshotofMainWindowMenu
        function SaveSnapshotofMainWindowMenuSelected(app, event)
            
        end

        % Menu selected function: PNGMenu
        function PNGMenuSelected(app, event)
            %cd(app.executableFolder);

            [file, path] = uiputfile('*.png', 'Save as');

            exportapp(app.NeuromodToolboxMainWindowUIFigure,fullfile(path,file)) 
        end

        % Menu selected function: JPGMenu
        function JPGMenuSelected(app, event)
            %cd(app.executableFolder);
            
            [file, path] = uiputfile('*.jpg', 'Save as');

            exportapp(app.NeuromodToolboxMainWindowUIFigure,fullfile(path,file)) 
        end

        % Menu selected function: ViewExportorDeleteDataComponentsMenu
        function ViewExportorDeleteDataComponentsMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                f = msgbox("Warning! No Data was extracted.");
                return;
            end

            app.Manage_Dataset_ComponentsWindow = Manage_Dataset_Components(app);

            [~] = Utility_Set_ToolTips(app,app.ShowToolTipsSetting,"ManageDataset");
        end

        % Button pushed function: RUNButton_5
        function RUNButton_5Pushed(app, event)
            for i = 1:length(app.CurrentModules.Functions)
                if strcmp(app.MainPlotAnalysisModulePanel.Title,app.CurrentModules.Title{i})
                    eval(strcat(app.CurrentModules.Functions{i},"(app,app.ListBox_6.Value);"));
                end
            end
        end

        % Menu selected function: AutorunManagerWindowMenu
        function AutorunManagerWindowMenuSelected(app, event)
            app.AutorunWindow = Autorun_Manager_Window(app);
            
            [~] = Utility_Set_ToolTips(app,app.ShowToolTipsSetting,"AutorunWindow");

        end

        % Menu selected function: XLabelMenu
        function XLabelMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end

            h = Figure_Property_Change_Window(app.UIAxes,'xlabel',app,"MainWindowDataXlabel");
            waitfor(h);
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,0,"Initial","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: YLabelMenu
        function YLabelMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end
            h = Figure_Property_Change_Window(app.UIAxes,'ylabel',app,"MainWindowDataYlabel");
            waitfor(h);
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,0,"Initial","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: FontSizeMenu
        function FontSizeMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end
            h = Figure_Property_Change_Window(app.UIAxes,'fontsize',app,"MainWindowDataFontSize");
            waitfor(h);
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,0,"Initial","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: LineWidthDataMenu
        function LineWidthDataMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end
            h = Figure_Property_Change_Window(app.UIAxes,'linewidth',app,"MainWindowDataLineWidthData");
            waitfor(h);
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,0,"Initial","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: BackgroundColorMenu_2
        function BackgroundColorMenu_2Selected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.PlotAppearance.MainWindow.Data.Color.MainBackground = selectedColor;
                app.UIAxes.Color = app.PlotAppearance.MainWindow.Data.Color.MainBackground;
            end

            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            %Organize_Prepare_Plot_and_Extract_GUI_Info(app,0,"Initial","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);
            
            Organize_Reset_Main_Plot(app,0,1,1,0,app.CurrentTimePoints);
        end

        % Menu selected function: RESETMenu
        function RESETMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end
            app.UIAxes.NextPlot = "replace"; 
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add"; 
            app.UIAxes.Box = "off";

            app.ClimMaxValues = [];

            % Check if the Y-axis is reversed
            if strcmp(app.UIAxes.YDir, 'reverse')
                if strcmp(app.PlotAppearance.MainWindow.Data.Plottype,"Imagesc")
                else
                    app.UIAxes.YDir = 'normal';
                end
            else
                if strcmp(app.PlotAppearance.MainWindow.Data.Plottype,"Imagesc")
                    app.UIAxes.YDir = 'reverse';
                end
            end

            [app.PlotAppearance] = Organize_Set_Standard_PlotAppearance("MainDataPlot",app.PlotAppearance);
            
            app.tempcolorMap = "parula";
            app.tempcolorMapset = eval(strcat(app.tempcolorMap,"(size(app.Data.Raw,1))")); % Example colormap: You can use any other colormap
            
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Initial","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);
            
            %Take care of potentially changed backgroundcolor
            app.UIAxes.Color = app.PlotAppearance.MainWindow.Data.Color.MainBackground;
            %app.UIAxes_2.Color = app.PlotAppearance.MainWindow.Data.Color.TimeBackground;
            app.UIAxes.FontSize = app.PlotAppearance.MainWindow.Data.MainFontSize;
            %app.UIAxes_2.FontSize = app.PlotAppearance.MainWindow.Data.TimeFontSize;

            Utility_Initialize_Clicks_Plots(app,"Static");
        end

        % Menu selected function: LineWidthSpikesMenu
        function LineWidthSpikesMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end
            h = Figure_Property_Change_Window(app.UIAxes,'LineWidthSpikes',app,"MainWindowDataLineWidthSpikes");
            waitfor(h);
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Initial","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: LineWidthEventsMenu
        function LineWidthEventsMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end
            h = Figure_Property_Change_Window(app.UIAxes,'LineWidthEvents',app,"MainWindowDataLineWidthEvent");
            waitfor(h);
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Initial","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: ColorSpikesMenu
        function ColorSpikesMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.PlotAppearance.MainWindow.Data.Color.MainSpikes = selectedColor;
            end

            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Initial","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: ColorEventsMenu
        function ColorEventsMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.PlotAppearance.MainWindow.Data.Color.MainEvents = selectedColor;
            end

            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Initial","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: TitleMenu_2
        function TitleMenu_2Selected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end
            h = Figure_Property_Change_Window(app.UIAxes_2,'title',app,"MainWindowTimeTitle");
            waitfor(h);
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Initial","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: FontSizeMenu_2
        function FontSizeMenu_2Selected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end
            h = Figure_Property_Change_Window(app.UIAxes_2,'fontsize',app,"MainWindowTimeFontSize");
            waitfor(h);
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Initial","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: LineWidthTimeRectangleMenu
        function LineWidthTimeRectangleMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end
            h = Figure_Property_Change_Window(app.UIAxes_2,'linewidth',app,"MainWindowTimeLineWidthRectangle");
            waitfor(h);
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Initial","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: LineWidthEventsMenu_2
        function LineWidthEventsMenu_2Selected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end
            h = Figure_Property_Change_Window(app.UIAxes_2,'linewidth',app,"MainWindowTimeLineWidthEvent");
            waitfor(h);
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Initial","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: BackgroundColorMenu
        function BackgroundColorMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.PlotAppearance.MainWindow.Data.Color.TimeBackground = selectedColor;
            end
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Initial","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: XLabelMenu_2
        function XLabelMenu_2Selected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end
            h = Figure_Property_Change_Window(app.UIAxes_2,'xlabel',app,"MainWindowTimeXlabel");
            waitfor(h);
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Initial","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: YLabelMenu_2
        function YLabelMenu_2Selected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end
            h = Figure_Property_Change_Window(app.UIAxes_2,'ylabel',app,"MainWindowTimeYlabel");
            waitfor(h);
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Initial","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: RESETMenu_2
        function RESETMenu_2Selected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end
            app.UIAxes_2.NextPlot = "replace"; 
            plot(app.UIAxes_2,0,0);
            app.UIAxes_2.NextPlot = "add"; 

            [app.PlotAppearance] = Organize_Set_Standard_PlotAppearance("MainTimePlot",app.PlotAppearance);
            
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Initial","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);
            
            %Take care of potentially changed backgroundcolor
            %app.UIAxes.Color = app.PlotAppearance.MainWindow.Data.Color.MainBackground;
            app.UIAxes_2.Color = app.PlotAppearance.MainWindow.Data.Color.TimeBackground;
            %app.UIAxes.FontSize = app.PlotAppearance.MainWindow.Data.MainFontSize;
            app.UIAxes_2.FontSize = app.PlotAppearance.MainWindow.Data.TimeFontSize;

            Utility_Initialize_Clicks_Plots(app,"Static");
        end

        % Menu selected function: ColorTimeRectangleMenu
        function ColorTimeRectangleMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.PlotAppearance.MainWindow.Data.Color.TimeRectangle = selectedColor;
            end
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Initial","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: ColorEventsMenu_2
        function ColorEventsMenu_2Selected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.PlotAppearance.MainWindow.Data.Color.TimeEvents = selectedColor;
            end
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Initial","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: PlotasPointsMenu
        function PlotasPointsMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end
            app.SpikePlotType = "Points";
            SpikeHandles = findobj(app.UIAxes, 'Type', 'line', 'Tag', 'Spikes');
            delete(SpikeHandles(:));
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Initial","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: PlotasWaveformsMenu
        function PlotasWaveformsMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end
            app.SpikePlotType = "Waveforms";
            SpikeHandles = findobj(app.UIAxes, 'Type', 'line', 'Tag', 'Spikes');
            delete(SpikeHandles(:));
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Initial","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: SaveCostumeAppearanceMenu
        function SaveCostumeAppearanceMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end
            app.PlotAppearance = Utility_Save_Load_Delete_Plot_Appearance(app.PlotAppearance,app.executableFolder,"Save");
        end

        % Menu selected function: DeleteCostumeAppearanceMenu
        function DeleteCostumeAppearanceMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end
            app.PlotAppearance = Utility_Save_Load_Delete_Plot_Appearance(app.PlotAppearance,app.executableFolder,"Delete");
        end

        % Menu selected function: SwitchAddModulesMenu
        function SwitchAddModulesMenuSelected(app, event)
            app.ManageModulesWindow = Manage_Modules_Window(app);

            [~] = Utility_Set_ToolTips(app,app.ShowToolTipsSetting,"ManageModuleWindow");
        end

        % Menu selected function: ColormapMenu
        function ColormapMenuSelected(app, event)
            
        end

        % Callback function
        function SliderValueChanged(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                f = msgbox("Warning! No Data was extracted.");
                return;
            end

            % Get descending y values for each channel plot
            app.PlotLineSpacing = app.Slider.Value;  % Height between each row plot
            % If data is normalized: Adjust so that raw data and
            % preprocessed data scale is not far off
            if isfield(app.Data.Info, 'Normalize') && strcmp(app.DropDown.Value,'Preprocessed Data')
                app.PlotLineSpacing = app.PlotLineSpacing*10;  % Height between each row plot
            end
            % If data is normalized and high pass filtered: Adjust so that raw data and
            % preprocessed data scale is not far off, difference even
            % bigger than just normalization
            if isfield(app.Data.Info, 'Normalize') && isfield(app.Data.Info, 'FilterMethod') && strcmp(app.DropDown.Value,'Preprocessed Data')
                if strcmp(app.Data.Info.FilterMethod,"High-Pass")
                    app.PlotLineSpacing = app.PlotLineSpacing*10;  % Height between each row plot
                end
            end
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,0,"Subsequent","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);

            % %Take care of potentially changed backgroundcolor
            % app.UIAxes.Color = app.PlotColor.MainBackgroundColor;
            
        end

        % Callback function
        function DropDownValueChanged(app, event)
            if strcmp(app.DropDown_2.Value,'Spikes') && strcmp(app.DropDown.Value,'Raw Data')
                warning("Spike locations are not accurate when viewing raw data! View with high pass filtered data to get accurate spike overlay positions for channel data.")
            end

            value = app.DropDown.Value;
            
            if strcmp(value,'Raw Data')
                DataPlotTitle = app.UIAxes.Title;
                if strcmp(DataPlotTitle.String,'Preprocessed Data') && isfield(app.Data.Info,'DownsampleFactor')
                    TimeinSecs = app.Data.TimeDownsampled(app.CurrentTimePoints);
                    % Calculate the absolute differences
                    differences = abs(app.Data.Time - TimeinSecs);
                    
                    % Find the index of the minimum difference
                    [~, app.CurrentTimePoints] = min(differences);

                    %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                    % Plot functions are fully autonomous without needed the app
                    % object. It is only needed to get the necessary parameter.
                    % Both is combined in one function for convenience.
                    %input 2: 1 if plot time, 0 if no time plot necessary
                    %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                    %input 4: Whether Data plot should run in a movie or not
                    Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Initial","Preprocessing",app.PlotEvents,app.Plotspikes);
                else
                    %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                    % Plot functions are fully autonomous without needed the app
                    % object. It is only needed to get the necessary parameter.
                    % Both is combined in one function for convenience.
                    %input 2: 1 if plot time, 0 if no time plot necessary
                    %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                    %input 4: Whether Data plot should run in a movie or not
                    Organize_Prepare_Plot_and_Extract_GUI_Info(app,0,"Subsequent","Preprocessing",app.PlotEvents,app.Plotspikes);
                end

            else % Preprocessed Data

                DataPlotTitle = app.UIAxes.Title;
                if strcmp(DataPlotTitle.String,'Raw Data') && isfield(app.Data.Info,'DownsampleFactor')
                    TimeinSecs = app.Data.Time(app.CurrentTimePoints);
                    % Calculate the absolute differences
                    differences = abs(app.Data.TimeDownsampled - TimeinSecs);
                    
                    % Find the index of the minimum difference
                    [~, app.CurrentTimePoints] = min(differences);

                    %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                    % Plot functions are fully autonomous without needed the app
                    % object. It is only needed to get the necessary parameter.
                    % Both is combined in one function for convenience.
                    %input 2: 1 if plot time, 0 if no time plot necessary
                    %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                    %input 4: Whether Data plot should run in a movie or not
                    Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Initial","Preprocessing",app.PlotEvents,app.Plotspikes);
                else
                    %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                    % Plot functions are fully autonomous without needed the app
                    % object. It is only needed to get the necessary parameter.
                    % Both is combined in one function for convenience.
                    %input 2: 1 if plot time, 0 if no time plot necessary
                    %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                    %input 4: Whether Data plot should run in a movie or not
                    Organize_Prepare_Plot_and_Extract_GUI_Info(app,0,"Subsequent","Preprocessing",app.PlotEvents,app.Plotspikes);
                end

            end

            app.UIAxes.Box = "off";
        end

        % Menu selected function: ChangeDataLineSpacingRangeMenu
        function ChangeDataLineSpacingRangeMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end
            Channel_Spacing_Selection(app,"MainWindow");
        end

        % Menu selected function: IndividualChannelLinesMenu
        function IndividualChannelLinesMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                f = msgbox("Warning! No Data was extracted.");
                return;
            end

            if ~isempty(app.ChannelAxes)
                for k = 1:numel(app.ChannelAxes)
                    if isvalid(app.ChannelAxes{k})  
                        delete(app.ChannelAxes{k});
                    end
                end
                app.ChannelAxes = [];
            end
            if ~isempty(app.ChannelGrid)
                delete(app.ChannelGrid)
            end

            if strcmp(app.PlotAppearance.MainWindow.Data.Plottype,"Surf") || strcmp(app.PlotAppearance.MainWindow.Data.Plottype,"Mesh") || strcmp(app.PlotAppearance.MainWindow.Data.Plottype,"AxonViewer")
                if strcmp(app.TimeRangeViewBox.Value,strcat(num2str(1/app.Data.Info.NativeSamplingRate),"s"))
                    app.TimeRangeViewBox.Value = '0.5s';
                    %msgbox(strcat("Current Time Span changed to ",app.TimeRangeViewBox.Value));
                end
            end
            
            app.MovieTimeToJump = 30;

            msg = sprintf([ ...
                'The following parameters were set for good visbility. If needed, change those settings in the *View/Change Plot Properties/Data Plot/Plot Type* menu option in the main window :\n\n' ...
                'Movie Number of Samples to Skip  = %g\n' ...
                'Main Window Current Time Span = %s' ], ...
                app.MovieTimeToJump, ...
                app.TimeRangeViewBox.Value);

            msgbox(msg,'Automatic Settings Applied for Imagsc View Mode!');

            app.Grid_Traces_View_Panel.Visible                = "off";
            app.Grid_Traces_View_Panel.Enable                 = "off";

            app.PlotAppearance.MainWindow.Data.Plottype = "Individual Lines";
            app.UIAxes.YDir = 'normal';
            app.ClimMaxValues = [];
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Initial","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);
            Organize_Reset_Main_Plot(app,1,1,1,0,app.CurrentTimePoints);
            Utility_Initialize_Clicks_Plots(app,"Static");
    
            app.UIAxes.YColor = 'k';
            app.UIAxes.XColor = 'k';
            app.UIAxes.ZColor = 'k';
            app.UIAxes.Title.Color = 'k';
            app.UIAxes.Color = app.PlotAppearance.MainWindow.Data.Color.MainBackground;
            view(app.UIAxes, 2)
            delete(app.UIAxes.Colorbar)
        end

        % Menu selected function: ImagescMenu
        function ImagescMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                f = msgbox("Warning! No Data was extracted.");
                return;
            end

            if strcmp(app.PlotAppearance.MainWindow.Data.Plottype,"Surf") || strcmp(app.PlotAppearance.MainWindow.Data.Plottype,"Mesh") || strcmp(app.PlotAppearance.MainWindow.Data.Plottype,"AxonViewer")
                if strcmp(app.TimeRangeViewBox.Value,strcat(num2str(1/app.Data.Info.NativeSamplingRate),"s"))
                    app.TimeRangeViewBox.Value = '0.5s';
                    %msgbox(strcat("Current Time Span changed to ",app.TimeRangeViewBox.Value));
                end
            end
            
            app.MovieTimeToJump = 30;

            msg = sprintf([ ...
                'The following parameters were set for good visbility. If needed, change those settings in the *View/Change Plot Properties/Data Plot/Plot Type* menu option in the main window :\n\n' ...
                'Movie Number of Samples to Skip  = %g\n' ...
                'Main Window Current Time Span = %s' ], ...
                app.MovieTimeToJump, ...
                app.TimeRangeViewBox.Value);

            msgbox(msg,'Automatic Settings Applied for Imagsc View Mode!');

            if ~isempty(app.ChannelAxes)
                for k = 1:numel(app.ChannelAxes)
                    if isvalid(app.ChannelAxes{k})  
                        delete(app.ChannelAxes{k});
                    end
                end
                app.ChannelAxes = [];
            end
            if ~isempty(app.ChannelGrid)
                delete(app.ChannelGrid)
            end

            if strcmp(app.PlotAppearance.MainWindow.Data.Plottype,"Surf") || strcmp(app.PlotAppearance.MainWindow.Data.Plottype,"Mesh") || strcmp(app.PlotAppearance.MainWindow.Data.Plottype,"AxonViewer")
                if ~strcmp(app.TimeRangeViewBox.Value,strcat(num2str(1/app.Data.Info.NativeSamplingRate),"s"))
                    app.TimeRangeViewBox.Value = strcat(num2str(1/app.Data.Info.NativeSamplingRate),"s");
                    msgbox(strcat("Current Time Span changed to ",app.TimeRangeViewBox.Value));
                end
            end

            app.Grid_Traces_View_Panel.Visible                = "off";
            app.Grid_Traces_View_Panel.Enable                 = "off";
            app.PlotAppearance.MainWindow.Data.Plottype = "Imagesc";
            app.UIAxes.YDir = 'reverse';
            app.ClimMaxValues = [];
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            ImageScChannel_handles = findobj(app.UIAxes,'Tag', 'ImageScChannel');
            delete(ImageScChannel_handles);

            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Initial","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);
            Organize_Reset_Main_Plot(app,1,1,1,0,app.CurrentTimePoints);

            app.UIAxes.YColor = 'k';
            app.UIAxes.XColor = 'k';
            app.UIAxes.ZColor = 'k';
            app.UIAxes.Title.Color = 'k';
            app.UIAxes.Color = app.PlotAppearance.MainWindow.Data.Color.MainBackground;
            view(app.UIAxes, 2)
            % Colorbar
            cb = colorbar(app.UIAxes, 'Location', 'westoutside');
            cb.Label.String = 'Signal [mV]';
            cb.Color = 'k';
        end

        % Callback function
        function OpenProbeViewButtonPushed(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end

            app.ProbeViewWindowHandle = Probe_View_Window(app,'MainWindow');

            [app] = Utility_Set_ToolTips(app,app.ShowToolTipsSetting,"ProbeViewMainWindow");
        end

        % Callback function
        function TimeRangeViewBoxValueChanged(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                f = msgbox("Warning! No Data was extracted.");
                return;
            end

            Organize_Set_MainWindow_TimeRange(app,str2double(app.TimeRangeViewBox.Value(1:end-1)),[0.33 app.Data.Time(end)],"NewTimeRange",event)
            
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Subsequent","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: TurnOnMenu_2
        function TurnOnMenu_2Selected(app, event)
            Organize_Delete_All_Open_Windows(app,0);

            [app] = Utility_Set_ToolTips(app,1,"All");
            app.ShowToolTipsSetting = 1;

            msgbox("Tooltips for all windows turned ON.")
        end

        % Menu selected function: TurnOffMenu_2
        function TurnOffMenu_2Selected(app, event)
            Organize_Delete_All_Open_Windows(app,0);

            [app] = Utility_Set_ToolTips(app,0,"All");
            app.ShowToolTipsSetting = 0;

            msgbox("Tooltips for all windows turned OFF.")
        end

        % Menu selected function: SaveasnewDefaultMenu_2
        function SaveasnewDefaultMenu_2Selected(app, event)
            FileToLoad = strcat(app.executableFolder,"\Modules\MISC\Variables (do not edit)\ShowToolTipsSetting.mat");
            if isfile(FileToLoad)
                delete(FileToLoad);
            end

            ShowToolTips = app.ShowToolTipsSetting;
            save(FileToLoad,"ShowToolTips");
            if app.ShowToolTipsSetting == 1
                msgbox("Succesfully set new default tooltip setting to ON");
            else
                msgbox("Succesfully set new default tooltip setting to OFF");
            end
        end

        % Menu selected function: ManualMenu
        function ManualMenuSelected(app, event)
            % Specify the full path to the file
            filePath = strcat(app.executableFolder,'\Modules\MISC\NeuroMod_Toolbox_Manual.docx');
            
            % Use the system command 'explorer' with the '/select,' argument to highlight the file
            system(['explorer /select, "', filePath, '"']);

            msgbox("Opened the folder and highlighted the file in windows file explorer!")
        end

        % Menu selected function: GithubMenu
        function GithubMenuSelected(app, event)
            % Specify the URL
            url = 'https://github.com/TonDSchul/Neuromod-Ephys-Analysis-Toolbox';
            
            % Open the URL in the system's default web browser
            web(url, '-browser');
        end

        % Menu selected function: ControlsMenu_2
        function ControlsMenu_2Selected(app, event)
            Controls_Window(app);
        end

        % Menu selected function: ResetPlotMenu
        function ResetPlotMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end
            
            app.PlotEvents = "Non";
            app.Plotspikes = "No";

            app.UIAxes_2.NextPlot = "replace"; 
            plot(app.UIAxes_2,0,0);
            app.UIAxes_2.NextPlot = "add"; 
            
            app.DropDown_2.Value = "Non";
            app.CurrentTimePoints = 1;

            Organize_Reset_Main_Plot(app,1,0,0,1,app.CurrentTimePoints);

            app = Utility_Change_Light_Dark_Mode(app,"MainWindow");
        end

        % Menu selected function: ChangeNumberofSamplestoSkipMenu
        function ChangeNumberofSamplestoSkipMenuSelected(app, event)
            Change_Plot_Speed(app,"Speed")
            
        end

        % Callback function
        function DropDown_2ValueChanged(app, event)
            value = app.DropDown_2.Value;
            
            if strcmp(value,'Events')
                % if already plotted
                if strcmp(app.PlotEvents,"Events")
                    Eventline_handlesData = findobj(app.UIAxes,'Type', 'line', 'Tag', 'Events');
                    if ~isempty(Eventline_handlesData)
                        delete(Eventline_handlesData(:));
                    end
                    Eventline_handlesTime = findobj(app.UIAxes_2, 'Tag','Eventlines');
                    if ~isempty(Eventline_handlesTime)
                        delete(Eventline_handlesTime(:));
                    end
                    app.PlotEvents = "No";
                else % if not plotted so far
                    app.PlotEvents = "Events";
                end
            end

            if strcmp(value,'Spikes')
                
                % if already plotted
                if strcmp(app.Plotspikes,"Spikes")
                    SpikeHandles = findobj(app.UIAxes, 'Type', 'line', 'Tag', 'Spikes');
                    if ~isempty(SpikeHandles)
                        delete(SpikeHandles(:));
                    end
                    app.Plotspikes = "No";
                else % if not plotted so far
                    app.Plotspikes = "Spikes";
                end
            end

            if strcmp(value,'Non')
                if strcmp(app.PlotEvents,"Events")
                    app.PlotEvents = "No";
                    Eventline_handlesData = findobj(app.UIAxes,'Type', 'line', 'Tag', 'Events');
                    if ~isempty(Eventline_handlesData)
                        delete(Eventline_handlesData(:));
                    end
                    Eventline_handlesTime = findobj(app.UIAxes_2, 'Tag','Eventlines');
                    if ~isempty(Eventline_handlesTime)
                        delete(Eventline_handlesTime(:));
                    end
                end
                if strcmp(app.Plotspikes,"Spikes")
                    app.Plotspikes = "No";
                    SpikeHandles = findobj(app.UIAxes, 'Type', 'line', 'Tag', 'Spikes');
                    if ~isempty(SpikeHandles)
                        delete(SpikeHandles(:));
                    end                   
                end
            end
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Initial","Events",app.PlotEvents,app.Plotspikes);
            
        end

        % Menu selected function: PythonPathSpikeInterfaceEnvironmentMenu
        function PythonPathSpikeInterfaceEnvironmentMenuSelected(app, event)
            % Python path
            filetocheck = strcat(app.executableFolder,"\Modules\MISC\Variables (do not edit)\Python_Conda_Path.mat");
            if exist(filetocheck,'file')
                delete(filetocheck)
                msgbox(strcat("File ",filetocheck," succesfully deleted!"))
            else
                msgbox(strcat("File ",filetocheck," not present!"))
            end
            
        end

        % Menu selected function: Spike2CEDS64MLinterfacelibraryMenu
        function Spike2CEDS64MLinterfacelibraryMenuSelected(app, event)
            % Spike2 Path
            filetocheck = strcat(app.executableFolder,'\Modules\MISC\Variables (do not edit)\CEDS64Path.mat');
            if exist(filetocheck,'file')
                delete(filetocheck)
                msgbox(strcat("File ",filetocheck," succesfully deleted!"))
            else
                msgbox(strcat("File ",filetocheck," not present!"))
            end


            filetocheck = strcat(app.executableFolder,'\Modules\MISC\Variables (do not edit)\ModuleOrder.mat');
            
        end

        % Button pushed function: Button_3
        function Button_3Pushed2(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                f = msgbox("Warning! No Data was extracted.");
                return;
            end
            
            app.PreviousThreshGrids = [];
            app.PlotThreshGrids = [];

            %% Determine new starting time point of data plot
           
            if isfield(app.Data.Info,'DownsampleFactor') && strcmp(app.DropDown.Value,'Preprocessed Data')
                [app] = Organize_Jump_in_Time(app,"Forward",numel(app.Data.TimeDownsampled),str2double(app.TimeRangeViewBox.Value(1:end-1))*app.Data.Info.DownsampledSampleRate,app.Data.Info.DownsampledSampleRate);
            else
                [app] = Organize_Jump_in_Time(app,"Forward",numel(app.Data.Time),str2double(app.TimeRangeViewBox.Value(1:end-1))*app.Data.Info.NativeSamplingRate,app.Data.Info.NativeSamplingRate);
            end
            
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Subsequent","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);
        end

        % Button pushed function: Button_4
        function Button_4Pushed2(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                f = msgbox("Warning! No Data was extracted.");
                return;
            end

            app.PreviousThreshGrids = [];
            app.PlotThreshGrids = [];
            %% Determine new starting time point of data plot

            if isfield(app.Data.Info,'DownsampleFactor') && strcmp(app.DropDown.Value,'Preprocessed Data')
                [app] = Organize_Jump_in_Time(app,"Backwards",numel(app.Data.TimeDownsampled),str2double(app.TimeRangeViewBox.Value(1:end-1)),app.Data.Info.DownsampledSampleRate);
            else
                [app] = Organize_Jump_in_Time(app,"Backwards",numel(app.Data.Time),str2double(app.TimeRangeViewBox.Value(1:end-1)),app.Data.Info.NativeSamplingRate);
            end

            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Subsequent","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed2(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                f = msgbox("Warning! No Data was extracted.");
                return;
            end
            % app.PreviousThreshGrids.SpikeChannelSelected
            app.CheckPlayMovie = 1;
            
            if isfield(app.Data.Info,'DownsampleFactor') && strcmp(app.DropDown.Value,'Preprocessed Data')
                TimeRangeofPlot = str2double(app.TimeRangeViewBox.Value(1:end-1))*app.Data.Info.DownsampledSampleRate;
                Maxtime = numel(app.Data.TimeDownsampled);
            else
                TimeRangeofPlot = str2double(app.TimeRangeViewBox.Value(1:end-1))*app.Data.Info.NativeSamplingRate;
                Maxtime = numel(app.Data.Time);
            end
            
            %app.PreviousThreshGrids = [];
            %app.PlotThreshGrids = [];
            % FirstTime = 1;
            while(app.CheckPlayMovie == 1)

                if app.CurrentTimePoints <= Maxtime-TimeRangeofPlot
                    

                    %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                    % Plot functions are fully autonomous without needed the app
                    % object. It is only needed to get the necessary parameter.
                    % Both is combined in one function for convenience.
                    %input 2: 1 if plot time, 0 if no time plot necessary
                    %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                    %input 4: Whether Data plot should run in a movie or not

                    Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Subsequent","MainWindowTimeManipulationMovie",app.PlotEvents,app.Plotspikes);
                    
                    app.CurrentTimePoints = round(app.CurrentTimePoints + app.MovieTimeToJump);

                else
                    app.CheckPlayMovie = 0;
                end
                
            end

            % app.MovieTimeToJump = OldMovieTimeToJump;
            
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed2(app, event)
            app.CheckPlayMovie = 0;
            app.PreviousThreshGrids = [];
            app.PlotThreshGrids = [];
        end

        % Button pushed function: Button_2
        function Button_2Pushed2(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                 msgbox("Warning! No Data was extracted.");
                return;
            end

            if strcmp(app.PlotAppearance.MainWindow.Data.Plottype,"AxonViewer") || strcmp(app.PlotAppearance.MainWindow.Data.Plottype,"Surf") || strcmp(app.PlotAppearance.MainWindow.Data.Plottype,"Mesh")
                msgbox("Cannot be changed in grid activity or axon view modes!");
                return;
            end
            
            app.PreviousThreshGrids = [];
            app.PlotThreshGrids = [];

            Organize_Set_MainWindow_TimeRange(app,str2double(app.TimeRangeViewBox.Value(1:end-1)),[0.001,app.Data.Time(end)],"Minus")
            
            % if app.ManuallyChangedPlotSpeed == 0
            %     app.MovieTimeToJump = 0.02;
            %     app.MovieTimeToJump = str2double(app.TimeRangeViewBox.Value(1:end-1))*app.MovieTimeToJump;
            % end
            
            if isprop(app.LiveECHTWindow,'ExistflagECHT')
                freqs = str2double(strsplit(app.LiveECHTWindow.NarrowbandCutoffLowerHigherEditField.Value,','));
                [app.LiveECHTWindow.NarrowbandFilterorderEditField.Value] = Utility_Determine_FIR_FilterOrder(app.LiveECHTWindow,freqs(1));
            end

            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Subsequent","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);
        end

        % Button pushed function: Button
        function ButtonPushed2(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end

            if strcmp(app.PlotAppearance.MainWindow.Data.Plottype,"AxonViewer") || strcmp(app.PlotAppearance.MainWindow.Data.Plottype,"Surf") || strcmp(app.PlotAppearance.MainWindow.Data.Plottype,"Mesh")
                msgbox("Cannot be changed in grid activity or axon view modes!");
                return;
            end
            
            app.PreviousThreshGrids = [];
            app.PlotThreshGrids = [];

            Organize_Set_MainWindow_TimeRange(app,str2double(app.TimeRangeViewBox.Value(1:end-1)),[0.001,app.Data.Time(end)],"Plus")
            
            if isprop(app.LiveECHTWindow,'ExistflagECHT')
                freqs = str2double(strsplit(app.LiveECHTWindow.NarrowbandCutoffLowerHigherEditField.Value,','));
                [app.LiveECHTWindow.NarrowbandFilterorderEditField.Value] = Utility_Determine_FIR_FilterOrder(app.LiveECHTWindow,freqs(1));
            end

            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Subsequent","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);
        end

        % Value changed function: TimeRangeViewBox
        function TimeRangeViewBoxValueChanged2(app, event)
            
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end
            
            if strcmp(app.PlotAppearance.MainWindow.Data.Plottype,"AxonViewer") || strcmp(app.PlotAppearance.MainWindow.Data.Plottype,"Surf") || strcmp(app.PlotAppearance.MainWindow.Data.Plottype,"Mesh")
                msgbox("Cannot be changed in grid activity or axon view modes!");
                return;
            end

            app.PreviousThreshGrids = [];
            app.PlotThreshGrids = [];

            Organize_Set_MainWindow_TimeRange(app,str2double(app.TimeRangeViewBox.Value(1:end-1)),[0.33 app.Data.Time(end)],"NewTimeRange",event)

            if isprop(app.LiveECHTWindow,'ExistflagECHT')
                freqs = str2double(strsplit(app.LiveECHTWindow.NarrowbandCutoffLowerHigherEditField.Value,','));
                [app.LiveECHTWindow.NarrowbandFilterorderEditField.Value] = Utility_Determine_FIR_FilterOrder(app.LiveECHTWindow,freqs(1));
            end
            
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Subsequent","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);
        end

        % Button pushed function: OpenProbeViewButton
        function OpenProbeViewButtonPushed2(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end

            app.ProbeViewWindowHandle = Probe_View_Window(app,'MainWindow');

            [app] = Utility_Set_ToolTips(app,app.ShowToolTipsSetting,"ProbeViewMainWindow");
        end

        % Value changed function: Slider
        function SliderValueChanged2(app, event)
            
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                f = msgbox("Warning! No Data was extracted.");
                return;
            end

            % Get descending y values for each channel plot
            app.PlotLineSpacing = app.Slider.Value;  % Height between each row plot
            % If data is normalized: Adjust so that raw data and
            % preprocessed data scale is not far off
            if isfield(app.Data.Info, 'Normalize') && strcmp(app.DropDown.Value,'Preprocessed Data')
                app.PlotLineSpacing = app.PlotLineSpacing*10;  % Height between each row plot
            end
            % If data is normalized and high pass filtered: Adjust so that raw data and
            % preprocessed data scale is not far off, difference even
            % bigger than just normalization
            if isfield(app.Data.Info, 'Normalize') && isfield(app.Data.Info, 'FilterMethod') && strcmp(app.DropDown.Value,'Preprocessed Data')
                if strcmp(app.Data.Info.FilterMethod,"High-Pass")
                    app.PlotLineSpacing = app.PlotLineSpacing*10;  % Height between each row plot
                end
            end
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,0,"Subsequent","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);

            % %Take care of potentially changed backgroundcolor
            % app.UIAxes.Color = app.PlotColor.MainBackgroundColor;
        end

        % Value changed function: DropDown
        function DropDownValueChanged2(app, event)

            if strcmp(app.DropDown_2.Value,'Spikes') && strcmp(app.DropDown.Value,'Raw Data')
                warning("Spike locations are not accurate when viewing raw data! View with high pass filtered data to get accurate spike overlay positions for channel data.")
            end
                   
            %% Update main plot
            value = app.DropDown.Value;
            
            app.ClimMaxValues = [];

            if strcmp(value,'Raw Data')
                DataPlotTitle = app.UIAxes.Title;
                if strcmp(DataPlotTitle.String,'Preprocessed Data') && isfield(app.Data.Info,'DownsampleFactor')
                    TimeinSecs = app.Data.TimeDownsampled(app.CurrentTimePoints);
                    % Calculate the absolute differences
                    differences = abs(app.Data.Time - TimeinSecs);
                    
                    % Find the index of the minimum difference
                    [~, app.CurrentTimePoints] = min(differences);

                    %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                    % Plot functions are fully autonomous without needed the app
                    % object. It is only needed to get the necessary parameter.
                    % Both is combined in one function for convenience.
                    %input 2: 1 if plot time, 0 if no time plot necessary
                    %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                    %input 4: Whether Data plot should run in a movie or not
                    Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Initial","Static",app.PlotEvents,app.Plotspikes);
                else
                    %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                    % Plot functions are fully autonomous without needed the app
                    % object. It is only needed to get the necessary parameter.
                    % Both is combined in one function for convenience.
                    %input 2: 1 if plot time, 0 if no time plot necessary
                    %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                    %input 4: Whether Data plot should run in a movie or not
                    Organize_Prepare_Plot_and_Extract_GUI_Info(app,0,"Subsequent","Static",app.PlotEvents,app.Plotspikes);
                end

            else % Preprocessed Data

                DataPlotTitle = app.UIAxes.Title;
                if strcmp(DataPlotTitle.String,'Raw Data') && isfield(app.Data.Info,'DownsampleFactor')
                    TimeinSecs = app.Data.Time(app.CurrentTimePoints);
                    % Calculate the absolute differences
                    differences = abs(app.Data.TimeDownsampled - TimeinSecs);
                    
                    % Find the index of the minimum difference
                    [~, app.CurrentTimePoints] = min(differences);

                    %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                    % Plot functions are fully autonomous without needed the app
                    % object. It is only needed to get the necessary parameter.
                    % Both is combined in one function for convenience.
                    %input 2: 1 if plot time, 0 if no time plot necessary
                    %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                    %input 4: Whether Data plot should run in a movie or not
                    Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Initial","Static",app.PlotEvents,app.Plotspikes);
                else
                    %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                    % Plot functions are fully autonomous without needed the app
                    % object. It is only needed to get the necessary parameter.
                    % Both is combined in one function for convenience.
                    %input 2: 1 if plot time, 0 if no time plot necessary
                    %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                    %input 4: Whether Data plot should run in a movie or not
                    Organize_Prepare_Plot_and_Extract_GUI_Info(app,0,"Subsequent","Static",app.PlotEvents,app.Plotspikes);
                end

            end
            
            
            app.UIAxes.Box = "off";

            app = Utility_Change_Light_Dark_Mode(app,"MainWindow");
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged2(app, event)
            value = app.DropDown_2.Value;
            value = app.DropDown_2.Value;
            
            if strcmp(value,'Events')
                % if already plotted
                if strcmp(app.PlotEvents,"Events")
                    Eventline_handlesData = findobj(app.UIAxes,'Type', 'line', 'Tag', 'Events');
                    if ~isempty(Eventline_handlesData)
                        delete(Eventline_handlesData(:));
                    end
                    Eventline_handlesTime = findobj(app.UIAxes_2, 'Tag','Eventlines');
                    if ~isempty(Eventline_handlesTime)
                        delete(Eventline_handlesTime(:));
                    end
                    EventText_handles = findobj(app.UIAxes, 'Tag','EventLabel');
                    if ~isempty(EventText_handles)
                        delete(EventText_handles(:));
                    end
                    app.PlotEvents = "No";
                else % if not plotted so far
                    app.PlotEvents = "Events";
                end
            end

            if strcmp(value,'Spikes')
                % if already plotted
                if strcmp(app.Plotspikes,"Spikes")
                    SpikeHandles = findobj(app.UIAxes, 'Type', 'line', 'Tag', 'Spikes');
                    if ~isempty(SpikeHandles)
                        delete(SpikeHandles(:));
                    end
                    app.Plotspikes = "No";
                    if ~isempty(app.ProbeViewWindowHandle)
                        SpikeHandles = findobj(app.ProbeViewWindowHandle.UIAxes, 'Tag', 'SpikePositions');
                        if ~isempty(SpikeHandles)
                            delete(SpikeHandles)
                        end
                    end
                else % if not plotted so far
                    app.Plotspikes = "Spikes";
                    if ~isempty(app.ProbeViewWindowHandle)
                        ProbeView_Show_Spike_Positions(app.Data,app.ProbeViewProperties,app.ProbeViewWindowHandle.UIAxes)
                    end
                end
            end

            if strcmp(value,'Non')
                if strcmp(app.PlotEvents,"Events")
                    app.PlotEvents = "No";
                    Eventline_handlesData = findobj(app.UIAxes,'Type', 'line', 'Tag', 'Events');
                    if ~isempty(Eventline_handlesData)
                        delete(Eventline_handlesData(:));
                    end
                    Eventline_handlesTime = findobj(app.UIAxes_2, 'Tag','Eventlines');
                    if ~isempty(Eventline_handlesTime)
                        delete(Eventline_handlesTime(:));
                    end
                    EventText_handles = findobj(app.UIAxes, 'Tag','EventLabel');
                    if ~isempty(EventText_handles)
                        delete(EventText_handles(:));
                    end
                end
                if strcmp(app.Plotspikes,"Spikes")
                    app.Plotspikes = "No";
                    SpikeHandles = findobj(app.UIAxes, 'Type', 'line', 'Tag', 'Spikes');
                    if ~isempty(SpikeHandles)
                        delete(SpikeHandles(:));
                    end      
                    if ~isempty(app.ProbeViewWindowHandle)
                        SpikeHandles = findobj(app.ProbeViewWindowHandle.UIAxes, 'Tag', 'SpikePositions');
                        if ~isempty(SpikeHandles)
                            delete(SpikeHandles)
                        end
                    end
                end
            end
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Initial","Events",app.PlotEvents,app.Plotspikes);

            app = Utility_Change_Light_Dark_Mode(app,"MainWindow");
        end

        % Value changed function: EventChannelDropDown
        function EventChannelDropDownValueChanged2(app, event)
            value = app.EventChannelDropDown.Value;
            
            for i = 1:length(app.Data.Info.EventChannelNames)
                if strcmp(app.Data.Info.EventChannelNames{i},value)
                    app.CurrentEventChannel = i;
                    app.EventTriggerNumberField.Value = strcat(num2str(length(app.Data.Events{app.CurrentEventChannel}))," event trigger.");
                end
            end

            EventText_handles = findobj(app.UIAxes, 'Tag','EventLabel');
            if ~isempty(EventText_handles)
                delete(EventText_handles(:));
            end
            
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            %Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Initial","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);
            Organize_Reset_Main_Plot(app,0,1,1,0,app.CurrentTimePoints);
            
            app = Utility_Change_Light_Dark_Mode(app,"MainWindow");
        end

        % Menu selected function: DarkModeDLMenu
        function DarkModeDLMenuSelected(app, event)
            Organize_Delete_All_Open_Windows(app,1)
            app.Colorscheme = 'DarkMode_Dark_Light';
            app = Utility_Change_Light_Dark_Mode(app,"MainWindow");

            if isfield(app.Data,'Raw') || isfield(app.Data,'Preprocessed')
                app.ProbeViewWindowHandle = Probe_View_Window(app,'MainWindow');
    
                [app] = Utility_Set_ToolTips(app,app.ShowToolTipsSetting,"ProbeViewMainWindow");
            end
        end

        % Menu selected function: DarkModeLDMenu
        function DarkModeLDMenuSelected(app, event)
            Organize_Delete_All_Open_Windows(app,1)
            app.Colorscheme = 'DarkMode_Light_Dark';
            app = Utility_Change_Light_Dark_Mode(app,"MainWindow");

            if isfield(app.Data,'Raw') || isfield(app.Data,'Preprocessed')
                app.ProbeViewWindowHandle = Probe_View_Window(app,'MainWindow');
    
                [app] = Utility_Set_ToolTips(app,app.ShowToolTipsSetting,"ProbeViewMainWindow");
            end
        end

        % Menu selected function: LightModeDLMenu
        function LightModeDLMenuSelected(app, event)
            Organize_Delete_All_Open_Windows(app,1)
            app.Colorscheme = 'LightMode_Dark_Light';
            app = Utility_Change_Light_Dark_Mode(app,"MainWindow");

            if isfield(app.Data,'Raw') || isfield(app.Data,'Preprocessed')
                app.ProbeViewWindowHandle = Probe_View_Window(app,'MainWindow');
    
                [app] = Utility_Set_ToolTips(app,app.ShowToolTipsSetting,"ProbeViewMainWindow");
            end
        end

        % Menu selected function: LightModeLDMenu
        function LightModeLDMenuSelected(app, event)
            Organize_Delete_All_Open_Windows(app,1)
            app.Colorscheme = 'LightMode_Light_Dark';
            app = Utility_Change_Light_Dark_Mode(app,"MainWindow");

            if isfield(app.Data,'Raw') || isfield(app.Data,'Preprocessed')
                app.ProbeViewWindowHandle = Probe_View_Window(app,'MainWindow');
    
                [app] = Utility_Set_ToolTips(app,app.ShowToolTipsSetting,"ProbeViewMainWindow");
            end
        end

        % Menu selected function: SetcurrentschemeasnewDefaultMenu
        function SetcurrentschemeasnewDefaultMenuSelected(app, event)
            SavePath = strcat(app.executableFolder,'/Modules/MISC/Variables (do not edit)/SavedColorScheme.mat');
            ColorScheme = app.Colorscheme;
            save(SavePath,"ColorScheme")
            
            msgbox("New default colorscheme succesfully saved.")
        end

        % Menu selected function: DeleteEventTriggerIndicesMenu
        function DeleteEventTriggerIndicesMenuSelected(app, event)
            EventsRelatedApp.TextArea = [];
            
            if isfield(app.Data,'Events')
                app.EventIndiceRejectionWindow = Preprocessing_Events_Trial_Rejection_Window(app,EventsRelatedApp,"MainWindow");
            else
                msgbox("No event data found! Please first extract event data.");
            end
            
        end

        % Menu selected function: PythonPathNEOEnvironmentMenu
        function PythonPathNEOEnvironmentMenuSelected(app, event)
            % Python path
            filetocheck = strcat(app.executableFolder,"\Modules\MISC\Variables (do not edit)\NEO_Python_Conda_Path.mat");
            if exist(filetocheck,'file')
                delete(filetocheck)
                msgbox(strcat("File ",filetocheck," succesfully deleted!"))
            else
                msgbox(strcat("File ",filetocheck," not present!"))
            end
        end

        % Menu selected function: SVGMenu
        function SVGMenuSelected(app, event)
            [file, path] = uiputfile('*.svg', 'Save as');
            
            if file == 0 || path == 0
                disp("File selection canceled.")
            else
                exportgraphics(app.NeuromodToolboxMainWindowUIFigure, fullfile(path,file), 'ContentType', 'vector');
            end
        end

        % Menu selected function: PythonPathPhyEnvironmentMenu
        function PythonPathPhyEnvironmentMenuSelected(app, event)
            % Python path
            filetocheck = strcat(app.executableFolder,"\Modules\MISC\Variables (do not edit)\Phy_Python_Conda_Path.mat");
            if exist(filetocheck,'file')
                delete(filetocheck)
                msgbox(strcat("File ",filetocheck," succesfully deleted!"))
            else
                msgbox(strcat("File ",filetocheck," not present!"))
            end
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            if isprop(app,'Data')
                if isfield(app.Data,'Raw')
                    Utility_Open_UIAxes_as_Figure(app.Data,app.UIAxes,"MainWindow");
                else
                    msgbox("Please extract data first!")
                end
            else
                msgbox("Please extract data first!")
            end
        end

        % Menu selected function: ONMenu
        function ONMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end

            app.PlotAppearance.MainWindow.Data.ShowTriggerNumber = 1;
            disp("Set to show trigger number in main plot to on.")

            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Initial","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: OFFMenu
        function OFFMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end

            EventText_handles = findobj(app.UIAxes, 'Tag','EventLabel');
            if ~isempty(EventText_handles)
                delete(EventText_handles(:));
            end

            app.PlotAppearance.MainWindow.Data.ShowTriggerNumber = 0;
            disp("Set to show trigger number in main plot to off.")

            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Initial","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: TrigerNumberTextColorMenu
        function TrigerNumberTextColorMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.PlotAppearance.MainWindow.Data.TriggerNrTextColor= selectedColor;
            end

            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Initial","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: TrigerNumberTextFontsizeMenu
        function TrigerNumberTextFontsizeMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end
            h = Figure_Property_Change_Window(app.UIAxes,'fontsize',app,"MainWindowTriggerNrTextFontSize");
            waitfor(h);
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,0,"Initial","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: AddAdditionalDelayMenu
        function AddAdditionalDelayMenuSelected(app, event)
            Change_Plot_Speed(app,"Delay")
        end

        % Context menu opening function: ContextMenu5
        function ContextMenu5Opening(app, event)
            
        end

        % Callback function
        function DGridActivityMenuSelected(app, event)
            
        end

        % Menu selected function: GridActivityViewMenu
        function GridActivityViewMenuSelected(app, event)
            
        end

        % Menu selected function: GridTraceViewMenu
        function GridTraceViewMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                f = msgbox("Warning! No Data was extracted.");
                return;
            end

            app.UIAxes.NextPlot = "replace"; 
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add"; 
            app.UIAxes.Box = "off";

            if strcmp(app.PlotAppearance.MainWindow.Data.Plottype,"Surf") || strcmp(app.PlotAppearance.MainWindow.Data.Plottype,"Mesh") || strcmp(app.PlotAppearance.MainWindow.Data.Plottype,"AxonViewer")
                if strcmp(app.TimeRangeViewBox.Value,strcat(num2str(1/app.Data.Info.NativeSamplingRate),"s"))
                    app.TimeRangeViewBox.Value = '0.1s';
                    %msgbox(strcat("Current Time Span changed to ",app.TimeRangeViewBox.Value));
                end
            end
            
            app.MovieTimeToJump = 30;

            msg = sprintf([ ...
                'The following parameters were set for good visbility. If needed, change those settings in the *View/Change Plot Properties/Data Plot/Plot Type* menu option in the main window :\n\n' ...
                'Movie Number of Samples to Skip  = %g\n' ...
                'Main Window Current Time Span = %s' ], ...
                app.MovieTimeToJump, ...
                app.TimeRangeViewBox.Value);

            msgbox(msg,'Automatic Settings Applied for Grid Traces View Mode!');

            app.PlotAppearance.MainWindow.Data.Plottype = "DataLinesGrid";
            app.UIAxes.YDir = 'reverse';
            app.ClimMaxValues = [];

            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            % ImageScChannel_handles = findobj(app.UIAxes,'Tag', 'ImageScChannel');
            % delete(ImageScChannel_handles);

            app.Grid_Traces_View_Panel.Visible                = "on";
            app.Grid_Traces_View_Panel.Enable                 = "on";

            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Initial","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);
            %Organize_Reset_Main_Plot(app,0,0,0,0,app.CurrentTimePoints);

            
        end

        % Menu selected function: ActivateLockedClimMenu
        function ActivateLockedClimMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end

            app.PlotAppearance.MainWindow.Data.LockedClimSettings = "on";
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Initial","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);

            
        end

        % Menu selected function: DeactivateLockedClimMenu
        function DeactivateLockedClimMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end

            app.PlotAppearance.MainWindow.Data.LockedClimSettings = "off";
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Initial","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);
        end

        % Context menu opening function: ContextMenu4
        function ContextMenu4Opening(app, event)
            
        end

        % Menu selected function: GridAxonViewerMenu
        function GridAxonViewerMenuSelected(app, event)
            
        end

        % Menu selected function: Show2DGridActivityMenu
        function Show2DGridActivityMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end

            if length(unique(app.Data.Info.ProbeInfo.xcoords))<=2 || length(unique(app.Data.Info.ProbeInfo.xcoords)) == 4 && app.Data.Info.ProbeInfo.OffSetRows == 1
                msgbox("Only available for three or more channel rows!");
                return;
            end

            if ~isempty(app.ChannelAxes)
                for k = 1:numel(app.ChannelAxes)
                    if isvalid(app.ChannelAxes{k})  
                        delete(app.ChannelAxes{k});
                    end
                end
                app.ChannelAxes = [];
            end
            if ~isempty(app.ChannelGrid)
                delete(app.ChannelGrid)
            end
            
            app.Grid_Traces_View_Panel.Visible                = "off";
            app.Grid_Traces_View_Panel.Enable                 = "off";
            
            if ~strcmp(app.TimeRangeViewBox.Value,strcat(num2str(1/app.Data.Info.NativeSamplingRate),"s"))
                app.TimeRangeViewBox.Value = strcat(num2str(1/app.Data.Info.NativeSamplingRate),"s");
                msgbox(strcat("Current Time Span changed to ",app.TimeRangeViewBox.Value));
            end

            app.UIAxes.NextPlot = "replace"; 
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add"; 
            app.UIAxes.Box = "off";

            app.PlotAppearance.MainWindow.Data.Plottype = "Surf";
            app.UIAxes.YDir = 'reverse';
            app.ClimMaxValues = [];

            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            ImageScChannel_handles = findobj(app.UIAxes,'Tag', 'ImageScChannel');
            delete(ImageScChannel_handles);

            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Subsequent","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);
            Organize_Reset_Main_Plot(app,1,0,0,0,app.CurrentTimePoints);

            app.UIAxes.YColor = 'k';
            app.UIAxes.XColor = 'k';
            app.UIAxes.ZColor = 'k';
            app.UIAxes.Title.Color = 'k';
            app.UIAxes.Color = app.PlotAppearance.MainWindow.Data.Color.MainBackground;
            view(app.UIAxes, 2)
            % Colorbar
            cb = colorbar(app.UIAxes, 'Location', 'westoutside');
            cb.Label.String = 'Signal [mV]';
            cb.Color = 'k';
        end

        % Menu selected function: Show3DGridActivityMenu
        function Show3DGridActivityMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end
            
            if length(unique(app.Data.Info.ProbeInfo.xcoords))<=2 || length(unique(app.Data.Info.ProbeInfo.xcoords)) == 4 && app.Data.Info.ProbeInfo.OffSetRows == 1
                msgbox("Only available for three or more channel rows!");
                return;
            end

            if ~isempty(app.ChannelAxes)
                for k = 1:numel(app.ChannelAxes)
                    if isvalid(app.ChannelAxes{k})  
                        delete(app.ChannelAxes{k});
                    end
                end
                app.ChannelAxes = [];
            end
            if ~isempty(app.ChannelGrid)
                delete(app.ChannelGrid)
            end

            
            app.Grid_Traces_View_Panel.Visible                = "off";
            app.Grid_Traces_View_Panel.Enable                 = "off";

            if ~strcmp(app.TimeRangeViewBox.Value,strcat(num2str(1/app.Data.Info.NativeSamplingRate),"s"))
                app.TimeRangeViewBox.Value = strcat(num2str(1/app.Data.Info.NativeSamplingRate),"s");
                msgbox(strcat("Current Time Span changed to ",app.TimeRangeViewBox.Value));
            end

            app.UIAxes.NextPlot = "replace"; 
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add"; 
            app.UIAxes.Box = "off";

            app.PlotAppearance.MainWindow.Data.Plottype = "Mesh";
            app.UIAxes.YDir = 'reverse';
            app.ClimMaxValues = [];
                        
            app.ClimMaxValues = [];
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            ImageScChannel_handles = findobj(app.UIAxes,'Tag', 'ImageScChannel');
            delete(ImageScChannel_handles);

            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Subsequent","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);
            Organize_Reset_Main_Plot(app,1,0,0,0,app.CurrentTimePoints);

            app.UIAxes.YColor = 'k';
            app.UIAxes.XColor = 'k';
            app.UIAxes.ZColor = 'k';
            app.UIAxes.Title.Color = 'k';
            app.UIAxes.Color = app.PlotAppearance.MainWindow.Data.Color.MainBackground;
            view(app.UIAxes, 45, 45)
            % Colorbar
            cb = colorbar(app.UIAxes, 'Location', 'westoutside');
            cb.Label.String = 'Signal [mV]';
            cb.Color = 'k';
        end

        % Menu selected function: ShowAxonViewerMenu
        function ShowAxonViewerMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end

            if length(unique(app.Data.Info.ProbeInfo.xcoords))<=2 || length(unique(app.Data.Info.ProbeInfo.xcoords)) == 4 && app.Data.Info.ProbeInfo.OffSetRows == 1
                msgbox("Only available for three or more channel rows!");
                return;
            end

            if isfield(app.Data.Info,'FilterMethod')
                if ~strcmp(app.Data.Info.FilterMethod,"High-Pass") && ~strcmp(app.Data.Info.FilterMethod,"Band-Pass")
                    msgbox("Data was not filtered but needs to be high-pass or band-pass filtered to track high-frequency components.")
                    return;
                end
            else
                msgbox("Data was not filtered but needs to be high-pass or band-pass filtered to track high-frequency components.")
                return;
            end

            if ~isempty(app.ChannelAxes)
                for k = 1:numel(app.ChannelAxes)
                    if isvalid(app.ChannelAxes{k})  
                        delete(app.ChannelAxes{k});
                    end
                end
                app.ChannelAxes = [];
            end
            if ~isempty(app.ChannelGrid)
                delete(app.ChannelGrid)
            end

            app.Grid_Traces_View_Panel.Visible                = "off";
            app.Grid_Traces_View_Panel.Enable                 = "off";
            
            if ~strcmp(app.TimeRangeViewBox.Value,strcat(num2str(1/app.Data.Info.NativeSamplingRate),"s"))
                app.TimeRangeViewBox.Value = strcat(num2str(1/app.Data.Info.NativeSamplingRate),"s");
                msgbox(strcat("Current Time Span changed to ",app.TimeRangeViewBox.Value));
            end

            app.UIAxes.NextPlot = "replace"; 
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add"; 
            app.UIAxes.Box = "off";

            app.PlotAppearance.MainWindow.Data.Plottype = "AxonViewer";
            app.UIAxes.YDir = 'reverse';
            app.ClimMaxValues = [];

            app.MovieTimeToJump = 1;
            if strcmp(app.DropDown.Value,"Raw Data")
                app.DropDown.Value = app.DropDown.Items{2};
            end
            
            app.PlotAppearance.MainWindow.Data.TimeAndSpaceInterpolation.SpaceInterpol = 2;
            app.PlotAppearance.MainWindow.Data.TimeAndSpaceInterpolation.TimeInterpol = 15;
            
            app.MovieTimeToJump = 1;
            app.PlotAppearance.MainWindow.Data.AxonViewerSpikeThreshold = 1;
            app.PlotAppearance.MainWindow.Data.AxonViewerPastSampleToSum = 500;
            app.PlotAppearance.MainWindow.Data.AxonViewerAdditionalThreshold = 0;
            app.PlotAppearance.MainWindow.Data.AxonViewerShowTrails = 0;
            app.PlotAppearance.MainWindow.Data.AxonViewerOnlyOnes = 0;

            msg = sprintf([ ...
                'The following parameters were set for good visbility. If needed, change those settings in the *View/Change Plot Properties/Data Plot/Plot Type* menu option in the main window :\n\n' ...
                'Movie Number of Samples to Skip  = %g\n' ...
                'Movie Additional Delay = %g\n' ...
                'Grid Spatial Resolution = %g\n' ...
                'Grid Time Resolution = %g\n' ...
                'Axon Viewer Number of Past Frames Considered = %g\n' ...
                'Axon Viewer Additional Threshold = %g\n' ...
                'Plot Show Trails = %d\n' ...
                'Set Numbers below threshold to -1 = %d\n' ...
                'Plot Selection = %s\n' ...
                'Main Window Current Time Span = %s' ], ...
                app.MovieTimeToJump, ...
                app.PlotAppearance.MainWindow.Data.AdditionalPlotDelay, ...
                app.PlotAppearance.MainWindow.Data.TimeAndSpaceInterpolation.SpaceInterpol, ...
                app.PlotAppearance.MainWindow.Data.TimeAndSpaceInterpolation.TimeInterpol, ...
                app.PlotAppearance.MainWindow.Data.AxonViewerPastSampleToSum, ...
                app.PlotAppearance.MainWindow.Data.AxonViewerAdditionalThreshold, ...
                double(app.PlotAppearance.MainWindow.Data.AxonViewerShowTrails), ...
                double(app.PlotAppearance.MainWindow.Data.AxonViewerOnlyOnes), ...
                app.DropDown.Value, ...
                app.TimeRangeViewBox.Value);

            msgbox(msg,'Automatic Settings Applied for Axon Viewer Movie Mode!');

            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            ImageScChannel_handles = findobj(app.UIAxes,'Tag', 'ImageScChannel');
            delete(ImageScChannel_handles);

            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Subsequent","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);
            Organize_Reset_Main_Plot(app,1,0,0,0,app.CurrentTimePoints);

            app.UIAxes.YColor = 'k';
            app.UIAxes.XColor = 'k';
            app.UIAxes.ZColor = 'k';
            app.UIAxes.Title.Color = 'k';
            app.UIAxes.Color = app.PlotAppearance.MainWindow.Data.Color.MainBackground;
            view(app.UIAxes, 2)
            % Colorbar
            cb = colorbar(app.UIAxes, 'Location', 'westoutside');
            cb.Label.String = 'Signal [mV]';
            cb.Color = 'k';
            cmap = colormap(app.UIAxes);
            colormap(app.UIAxes, flipud(cmap));
        end

        % Menu selected function: ChangeSpatialResolutionMenu_2
        function ChangeSpatialResolutionMenu_2Selected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end

            if length(unique(app.Data.Info.ProbeInfo.xcoords))<=2 || length(unique(app.Data.Info.ProbeInfo.xcoords)) == 4 && app.Data.Info.ProbeInfo.OffSetRows == 1
                msgbox("Only available for three or more channel rows!");
                return;
            end

            h = Channel_Spacing_Selection(app,"InterpolateSpaceSurf");
            waitfor(h);

            app.PreviousThreshGrids = [];
            app.PlotThreshGrids = [];

            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Subsequent","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: ChangeTimeResulotionMenu_2
        function ChangeTimeResulotionMenu_2Selected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end

            if length(unique(app.Data.Info.ProbeInfo.xcoords))<=2 || length(unique(app.Data.Info.ProbeInfo.xcoords)) == 4 && app.Data.Info.ProbeInfo.OffSetRows == 1
                msgbox("Only available for three or more channel rows!");
                return;
            end

            Channel_Spacing_Selection(app,"InterpolateTimeSurf");
        end

        % Menu selected function: SpikeDetectionThresholdMenu
        function SpikeDetectionThresholdMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end

            if length(unique(app.Data.Info.ProbeInfo.xcoords))<=2 || length(unique(app.Data.Info.ProbeInfo.xcoords)) == 4 && app.Data.Info.ProbeInfo.OffSetRows == 1
                msgbox("Only available for three or more channel rows!");
                return;
            end

            h = Channel_Spacing_Selection(app,"SpikeDetectionThreshold");
            waitfor(h);

            app.PreviousThreshGrids = [];
            app.PlotThreshGrids = [];

            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Subsequent","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: NumberofPastFramesConsideredMenu_2
        function NumberofPastFramesConsideredMenu_2Selected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end

            if length(unique(app.Data.Info.ProbeInfo.xcoords))<=2 || length(unique(app.Data.Info.ProbeInfo.xcoords)) == 4 && app.Data.Info.ProbeInfo.OffSetRows == 1
                msgbox("Only available for three or more channel rows!");
                return;
            end

            h = Channel_Spacing_Selection(app,"NumberofPastFramesConsidered");
            waitfor(h);

            app.PreviousThreshGrids = [];
            app.PlotThreshGrids = [];

            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Subsequent","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: ONMenu_2
        function ONMenu_2Selected(app, event)
             if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end

            if length(unique(app.Data.Info.ProbeInfo.xcoords))<=2 || length(unique(app.Data.Info.ProbeInfo.xcoords)) == 4 && app.Data.Info.ProbeInfo.OffSetRows == 1
                msgbox("Only available for three or more channel rows!");
                return;
            end

            app.PlotAppearance.MainWindow.Data.AxonViewerShowTrails = 1;

            app.PreviousThreshGrids = [];
            app.PlotThreshGrids = [];

            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Subsequent","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);

            
        end

        % Menu selected function: OFFMenu_2
        function OFFMenu_2Selected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end

            if length(unique(app.Data.Info.ProbeInfo.xcoords))<=2 || length(unique(app.Data.Info.ProbeInfo.xcoords)) == 4 && app.Data.Info.ProbeInfo.OffSetRows == 1
                msgbox("Only available for three or more channel rows!");
                return;
            end

            app.PlotAppearance.MainWindow.Data.AxonViewerShowTrails = 0;

            app.PreviousThreshGrids = [];
            app.PlotThreshGrids = [];

            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Subsequent","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: SetAllValuesBelowThresholdto1Menu
        function SetAllValuesBelowThresholdto1MenuSelected(app, event)
            
        end

        % Menu selected function: ONMenu_3
        function ONMenu_3Selected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end

            if length(unique(app.Data.Info.ProbeInfo.xcoords))<=2 || length(unique(app.Data.Info.ProbeInfo.xcoords)) == 4 && app.Data.Info.ProbeInfo.OffSetRows == 1
                msgbox("Only available for three or more channel rows!");
                return;
            end

            app.PlotAppearance.MainWindow.Data.AxonViewerOnlyOnes = 1;

            app.PreviousThreshGrids = [];
            app.PlotThreshGrids = [];

            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Subsequent","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);

            
        end

        % Menu selected function: OFFMenu_3
        function OFFMenu_3Selected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end

            if length(unique(app.Data.Info.ProbeInfo.xcoords))<=2 || length(unique(app.Data.Info.ProbeInfo.xcoords)) == 4 && app.Data.Info.ProbeInfo.OffSetRows == 1
                msgbox("Only available for three or more channel rows!");
                return;
            end

            app.PlotAppearance.MainWindow.Data.AxonViewerOnlyOnes = 0;

            app.PreviousThreshGrids = [];
            app.PlotThreshGrids = [];

            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Subsequent","MainWindowTimeManipulation",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: SpikeSelectorMenu
        function SpikeSelectorMenuSelected(app, event)
            app.SpikeSelectorWindow = Axon_Viewer_Select_Spike(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.NeuromodToolboxMainWindowUIFigure = uifigure('Visible', 'off');
            app.NeuromodToolboxMainWindowUIFigure.Name = 'Neuromod Toolbox Main Window';
            app.NeuromodToolboxMainWindowUIFigure.Icon = fullfile(pathToMLAPP, 'Modules', 'MISC', 'Images', 'Logo.png');
            app.NeuromodToolboxMainWindowUIFigure.CloseRequestFcn = createCallbackFcn(app, @NeuromodToolboxMainWindowUIFigureCloseRequest, true);
            app.ViewMenu = uimenu(app.NeuromodToolboxMainWindowUIFigure);
            app.ViewMenu.Text = '   View   ';
            app.ChangePlotPropertiesMenu = uimenu(app.ViewMenu);
            app.ChangePlotPropertiesMenu.Text = 'Change Plot Properties';
            app.DataPlotMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.DataPlotMenu.Text = 'Data Plot';
            app.PlotTypeMenu = uimenu(app.DataPlotMenu);
            app.PlotTypeMenu.Text = 'Plot Type';
            app.IndividualChannelLinesMenu = uimenu(app.PlotTypeMenu);
            app.IndividualChannelLinesMenu.MenuSelectedFcn = createCallbackFcn(app, @IndividualChannelLinesMenuSelected, true);
            app.IndividualChannelLinesMenu.Text = 'Individual Channel Lines';
            app.ImagescMenu = uimenu(app.PlotTypeMenu);
            app.ImagescMenu.MenuSelectedFcn = createCallbackFcn(app, @ImagescMenuSelected, true);
            app.ImagescMenu.Text = 'Imagesc';
            app.GridTraceViewMenu = uimenu(app.PlotTypeMenu);
            app.GridTraceViewMenu.MenuSelectedFcn = createCallbackFcn(app, @GridTraceViewMenuSelected, true);
            app.GridTraceViewMenu.Text = 'Grid Trace View';
            app.GridActivityViewMenu = uimenu(app.PlotTypeMenu);
            app.GridActivityViewMenu.MenuSelectedFcn = createCallbackFcn(app, @GridActivityViewMenuSelected, true);
            app.GridActivityViewMenu.Text = 'Grid Activity View';
            app.Show2DGridActivityMenu = uimenu(app.GridActivityViewMenu);
            app.Show2DGridActivityMenu.MenuSelectedFcn = createCallbackFcn(app, @Show2DGridActivityMenuSelected, true);
            app.Show2DGridActivityMenu.Text = 'Show 2D Grid Activity';
            app.Show3DGridActivityMenu = uimenu(app.GridActivityViewMenu);
            app.Show3DGridActivityMenu.MenuSelectedFcn = createCallbackFcn(app, @Show3DGridActivityMenuSelected, true);
            app.Show3DGridActivityMenu.Text = 'Show 3D Grid Activity';
            app.GridAxonViewerMenu = uimenu(app.PlotTypeMenu);
            app.GridAxonViewerMenu.MenuSelectedFcn = createCallbackFcn(app, @GridAxonViewerMenuSelected, true);
            app.GridAxonViewerMenu.Text = 'Grid Axon Viewer';
            app.ShowAxonViewerMenu = uimenu(app.GridAxonViewerMenu);
            app.ShowAxonViewerMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowAxonViewerMenuSelected, true);
            app.ShowAxonViewerMenu.Text = 'Show Axon Viewer';
            app.SpikeSelectorMenu = uimenu(app.GridAxonViewerMenu);
            app.SpikeSelectorMenu.MenuSelectedFcn = createCallbackFcn(app, @SpikeSelectorMenuSelected, true);
            app.SpikeSelectorMenu.Text = 'Spike Selector';
            app.SpikeDetectionThresholdMenu = uimenu(app.GridAxonViewerMenu);
            app.SpikeDetectionThresholdMenu.MenuSelectedFcn = createCallbackFcn(app, @SpikeDetectionThresholdMenuSelected, true);
            app.SpikeDetectionThresholdMenu.Text = 'Spike Detection Threshold';
            app.VisualizePathTrailsMenu = uimenu(app.GridAxonViewerMenu);
            app.VisualizePathTrailsMenu.Text = 'Visualize Path Trails';
            app.ONMenu_2 = uimenu(app.VisualizePathTrailsMenu);
            app.ONMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ONMenu_2Selected, true);
            app.ONMenu_2.Text = 'ON';
            app.OFFMenu_2 = uimenu(app.VisualizePathTrailsMenu);
            app.OFFMenu_2.MenuSelectedFcn = createCallbackFcn(app, @OFFMenu_2Selected, true);
            app.OFFMenu_2.Text = 'OFF';
            app.NumberofPastFramesConsideredMenu_2 = uimenu(app.VisualizePathTrailsMenu);
            app.NumberofPastFramesConsideredMenu_2.MenuSelectedFcn = createCallbackFcn(app, @NumberofPastFramesConsideredMenu_2Selected, true);
            app.NumberofPastFramesConsideredMenu_2.Text = 'Number of Past Frames Considered';
            app.SetAllValuesBelowThresholdto1Menu = uimenu(app.VisualizePathTrailsMenu);
            app.SetAllValuesBelowThresholdto1Menu.MenuSelectedFcn = createCallbackFcn(app, @SetAllValuesBelowThresholdto1MenuSelected, true);
            app.SetAllValuesBelowThresholdto1Menu.Text = 'Set All Values Below Threshold to 1';
            app.ONMenu_3 = uimenu(app.SetAllValuesBelowThresholdto1Menu);
            app.ONMenu_3.MenuSelectedFcn = createCallbackFcn(app, @ONMenu_3Selected, true);
            app.ONMenu_3.Text = 'ON';
            app.OFFMenu_3 = uimenu(app.SetAllValuesBelowThresholdto1Menu);
            app.OFFMenu_3.MenuSelectedFcn = createCallbackFcn(app, @OFFMenu_3Selected, true);
            app.OFFMenu_3.Text = 'OFF';
            app.ClimSettingsMenu = uimenu(app.PlotTypeMenu);
            app.ClimSettingsMenu.Text = 'Clim Settings';
            app.ActivateLockedClimMenu = uimenu(app.ClimSettingsMenu);
            app.ActivateLockedClimMenu.MenuSelectedFcn = createCallbackFcn(app, @ActivateLockedClimMenuSelected, true);
            app.ActivateLockedClimMenu.Text = 'Activate Locked Clim';
            app.DeactivateLockedClimMenu = uimenu(app.ClimSettingsMenu);
            app.DeactivateLockedClimMenu.MenuSelectedFcn = createCallbackFcn(app, @DeactivateLockedClimMenuSelected, true);
            app.DeactivateLockedClimMenu.Text = 'Deactivate Locked Clim';
            app.ChangeGridSettingsMenu = uimenu(app.PlotTypeMenu);
            app.ChangeGridSettingsMenu.Text = 'Change Grid Settings';
            app.ChangeSpatialResolutionMenu_2 = uimenu(app.ChangeGridSettingsMenu);
            app.ChangeSpatialResolutionMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ChangeSpatialResolutionMenu_2Selected, true);
            app.ChangeSpatialResolutionMenu_2.Text = 'Change Spatial Resolution';
            app.ChangeTimeResulotionMenu_2 = uimenu(app.ChangeGridSettingsMenu);
            app.ChangeTimeResulotionMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ChangeTimeResulotionMenu_2Selected, true);
            app.ChangeTimeResulotionMenu_2.Text = 'Change Time Resulotion';
            app.ColormapMenu = uimenu(app.DataPlotMenu);
            app.ColormapMenu.MenuSelectedFcn = createCallbackFcn(app, @ColormapMenuSelected, true);
            app.ColormapMenu.Text = 'Colormap';
            app.parulaMenu = uimenu(app.ColormapMenu);
            app.parulaMenu.MenuSelectedFcn = createCallbackFcn(app, @parulaMenuSelected, true);
            app.parulaMenu.Text = 'parula';
            app.turboMenu = uimenu(app.ColormapMenu);
            app.turboMenu.MenuSelectedFcn = createCallbackFcn(app, @turboMenuSelected, true);
            app.turboMenu.Text = 'turbo';
            app.hsvMenu = uimenu(app.ColormapMenu);
            app.hsvMenu.MenuSelectedFcn = createCallbackFcn(app, @hsvMenuSelected, true);
            app.hsvMenu.Text = 'hsv';
            app.hotMenu = uimenu(app.ColormapMenu);
            app.hotMenu.MenuSelectedFcn = createCallbackFcn(app, @hotMenuSelected, true);
            app.hotMenu.Text = 'hot';
            app.coolMenu = uimenu(app.ColormapMenu);
            app.coolMenu.MenuSelectedFcn = createCallbackFcn(app, @coolMenuSelected, true);
            app.coolMenu.Text = 'cool';
            app.springMenu = uimenu(app.ColormapMenu);
            app.springMenu.MenuSelectedFcn = createCallbackFcn(app, @springMenuSelected, true);
            app.springMenu.Text = 'spring';
            app.summerMenu = uimenu(app.ColormapMenu);
            app.summerMenu.MenuSelectedFcn = createCallbackFcn(app, @summerMenuSelected, true);
            app.summerMenu.Text = 'summer';
            app.autumnMenu = uimenu(app.ColormapMenu);
            app.autumnMenu.MenuSelectedFcn = createCallbackFcn(app, @autumnMenuSelected, true);
            app.autumnMenu.Text = 'autumn';
            app.grayMenu = uimenu(app.ColormapMenu);
            app.grayMenu.MenuSelectedFcn = createCallbackFcn(app, @grayMenuSelected, true);
            app.grayMenu.Text = 'gray';
            app.boneMenu = uimenu(app.ColormapMenu);
            app.boneMenu.MenuSelectedFcn = createCallbackFcn(app, @boneMenuSelected, true);
            app.boneMenu.Text = 'bone';
            app.FontSizeMenu = uimenu(app.DataPlotMenu);
            app.FontSizeMenu.MenuSelectedFcn = createCallbackFcn(app, @FontSizeMenuSelected, true);
            app.FontSizeMenu.Text = 'FontSize';
            app.LineWidthDataMenu = uimenu(app.DataPlotMenu);
            app.LineWidthDataMenu.MenuSelectedFcn = createCallbackFcn(app, @LineWidthDataMenuSelected, true);
            app.LineWidthDataMenu.Text = 'LineWidth Data';
            app.LineWidthSpikesMenu = uimenu(app.DataPlotMenu);
            app.LineWidthSpikesMenu.MenuSelectedFcn = createCallbackFcn(app, @LineWidthSpikesMenuSelected, true);
            app.LineWidthSpikesMenu.Text = 'LineWidth Spikes';
            app.LineWidthEventsMenu = uimenu(app.DataPlotMenu);
            app.LineWidthEventsMenu.MenuSelectedFcn = createCallbackFcn(app, @LineWidthEventsMenuSelected, true);
            app.LineWidthEventsMenu.Text = 'LineWidth Events';
            app.ShowTriggerNumberMenu = uimenu(app.DataPlotMenu);
            app.ShowTriggerNumberMenu.Text = 'Show Trigger Number';
            app.ONMenu = uimenu(app.ShowTriggerNumberMenu);
            app.ONMenu.MenuSelectedFcn = createCallbackFcn(app, @ONMenuSelected, true);
            app.ONMenu.Text = 'ON';
            app.OFFMenu = uimenu(app.ShowTriggerNumberMenu);
            app.OFFMenu.MenuSelectedFcn = createCallbackFcn(app, @OFFMenuSelected, true);
            app.OFFMenu.Text = 'OFF';
            app.TrigerNumberTextFontsizeMenu = uimenu(app.DataPlotMenu);
            app.TrigerNumberTextFontsizeMenu.MenuSelectedFcn = createCallbackFcn(app, @TrigerNumberTextFontsizeMenuSelected, true);
            app.TrigerNumberTextFontsizeMenu.Text = 'Triger Number Text Fontsize';
            app.TrigerNumberTextColorMenu = uimenu(app.DataPlotMenu);
            app.TrigerNumberTextColorMenu.MenuSelectedFcn = createCallbackFcn(app, @TrigerNumberTextColorMenuSelected, true);
            app.TrigerNumberTextColorMenu.Text = 'Triger Number Text Color';
            app.ColorSpikesMenu = uimenu(app.DataPlotMenu);
            app.ColorSpikesMenu.MenuSelectedFcn = createCallbackFcn(app, @ColorSpikesMenuSelected, true);
            app.ColorSpikesMenu.Text = 'Color Spikes';
            app.ColorEventsMenu = uimenu(app.DataPlotMenu);
            app.ColorEventsMenu.MenuSelectedFcn = createCallbackFcn(app, @ColorEventsMenuSelected, true);
            app.ColorEventsMenu.Text = 'Color Events';
            app.BackgroundColorMenu_2 = uimenu(app.DataPlotMenu);
            app.BackgroundColorMenu_2.MenuSelectedFcn = createCallbackFcn(app, @BackgroundColorMenu_2Selected, true);
            app.BackgroundColorMenu_2.Text = 'Background Color';
            app.XLabelMenu = uimenu(app.DataPlotMenu);
            app.XLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @XLabelMenuSelected, true);
            app.XLabelMenu.Text = 'XLabel';
            app.YLabelMenu = uimenu(app.DataPlotMenu);
            app.YLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @YLabelMenuSelected, true);
            app.YLabelMenu.Text = 'YLabel';
            app.RESETMenu = uimenu(app.DataPlotMenu);
            app.RESETMenu.MenuSelectedFcn = createCallbackFcn(app, @RESETMenuSelected, true);
            app.RESETMenu.Text = 'RESET';
            app.TimePlotMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.TimePlotMenu.Text = 'Time Plot';
            app.TitleMenu_2 = uimenu(app.TimePlotMenu);
            app.TitleMenu_2.MenuSelectedFcn = createCallbackFcn(app, @TitleMenu_2Selected, true);
            app.TitleMenu_2.Text = 'Title';
            app.FontSizeMenu_2 = uimenu(app.TimePlotMenu);
            app.FontSizeMenu_2.MenuSelectedFcn = createCallbackFcn(app, @FontSizeMenu_2Selected, true);
            app.FontSizeMenu_2.Text = 'FontSize';
            app.ColorTimeRectangleMenu = uimenu(app.TimePlotMenu);
            app.ColorTimeRectangleMenu.MenuSelectedFcn = createCallbackFcn(app, @ColorTimeRectangleMenuSelected, true);
            app.ColorTimeRectangleMenu.Text = 'Color Time Rectangle';
            app.ColorEventsMenu_2 = uimenu(app.TimePlotMenu);
            app.ColorEventsMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ColorEventsMenu_2Selected, true);
            app.ColorEventsMenu_2.Text = 'Color Events';
            app.LineWidthTimeRectangleMenu = uimenu(app.TimePlotMenu);
            app.LineWidthTimeRectangleMenu.MenuSelectedFcn = createCallbackFcn(app, @LineWidthTimeRectangleMenuSelected, true);
            app.LineWidthTimeRectangleMenu.Text = 'LineWidth Time Rectangle';
            app.LineWidthEventsMenu_2 = uimenu(app.TimePlotMenu);
            app.LineWidthEventsMenu_2.MenuSelectedFcn = createCallbackFcn(app, @LineWidthEventsMenu_2Selected, true);
            app.LineWidthEventsMenu_2.Text = 'LineWidth Events';
            app.BackgroundColorMenu = uimenu(app.TimePlotMenu);
            app.BackgroundColorMenu.MenuSelectedFcn = createCallbackFcn(app, @BackgroundColorMenuSelected, true);
            app.BackgroundColorMenu.Text = 'Background Color';
            app.XLabelMenu_2 = uimenu(app.TimePlotMenu);
            app.XLabelMenu_2.MenuSelectedFcn = createCallbackFcn(app, @XLabelMenu_2Selected, true);
            app.XLabelMenu_2.Text = 'XLabel';
            app.YLabelMenu_2 = uimenu(app.TimePlotMenu);
            app.YLabelMenu_2.MenuSelectedFcn = createCallbackFcn(app, @YLabelMenu_2Selected, true);
            app.YLabelMenu_2.Text = 'YLabel';
            app.RESETMenu_2 = uimenu(app.TimePlotMenu);
            app.RESETMenu_2.MenuSelectedFcn = createCallbackFcn(app, @RESETMenu_2Selected, true);
            app.RESETMenu_2.Text = 'RESET';
            app.SpikePlotAppearanceMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.SpikePlotAppearanceMenu.Text = 'Spike Plot Appearance';
            app.PlotasPointsMenu = uimenu(app.SpikePlotAppearanceMenu);
            app.PlotasPointsMenu.MenuSelectedFcn = createCallbackFcn(app, @PlotasPointsMenuSelected, true);
            app.PlotasPointsMenu.Text = 'Plot as Points';
            app.PlotasWaveformsMenu = uimenu(app.SpikePlotAppearanceMenu);
            app.PlotasWaveformsMenu.MenuSelectedFcn = createCallbackFcn(app, @PlotasWaveformsMenuSelected, true);
            app.PlotasWaveformsMenu.Text = 'Plot as Waveforms';
            app.SaveCostumeAppearanceMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.SaveCostumeAppearanceMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveCostumeAppearanceMenuSelected, true);
            app.SaveCostumeAppearanceMenu.Text = 'Save Costume Appearance';
            app.DeleteCostumeAppearanceMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.DeleteCostumeAppearanceMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteCostumeAppearanceMenuSelected, true);
            app.DeleteCostumeAppearanceMenu.Text = 'Delete Costume Appearance';
            app.ChangeColorschemeMenu = uimenu(app.ViewMenu);
            app.ChangeColorschemeMenu.Text = 'Change Colorscheme';
            app.DarkModeDLMenu = uimenu(app.ChangeColorschemeMenu);
            app.DarkModeDLMenu.MenuSelectedFcn = createCallbackFcn(app, @DarkModeDLMenuSelected, true);
            app.DarkModeDLMenu.Text = 'Dark Mode D/L';
            app.DarkModeLDMenu = uimenu(app.ChangeColorschemeMenu);
            app.DarkModeLDMenu.MenuSelectedFcn = createCallbackFcn(app, @DarkModeLDMenuSelected, true);
            app.DarkModeLDMenu.Text = 'Dark Mode L/D';
            app.LightModeDLMenu = uimenu(app.ChangeColorschemeMenu);
            app.LightModeDLMenu.MenuSelectedFcn = createCallbackFcn(app, @LightModeDLMenuSelected, true);
            app.LightModeDLMenu.Text = 'Light Mode D/L';
            app.LightModeLDMenu = uimenu(app.ChangeColorschemeMenu);
            app.LightModeLDMenu.MenuSelectedFcn = createCallbackFcn(app, @LightModeLDMenuSelected, true);
            app.LightModeLDMenu.Text = 'Light Mode L/D';
            app.SetcurrentschemeasnewDefaultMenu = uimenu(app.ChangeColorschemeMenu);
            app.SetcurrentschemeasnewDefaultMenu.MenuSelectedFcn = createCallbackFcn(app, @SetcurrentschemeasnewDefaultMenuSelected, true);
            app.SetcurrentschemeasnewDefaultMenu.Text = 'Set current scheme as new Default';
            app.FullscreenMenu = uimenu(app.ViewMenu);
            app.FullscreenMenu.MenuSelectedFcn = createCallbackFcn(app, @FullscreenMenuSelected, true);
            app.FullscreenMenu.Text = 'Fullscreen';
            app.WindowedMenu = uimenu(app.ViewMenu);
            app.WindowedMenu.MenuSelectedFcn = createCallbackFcn(app, @WindowedMenuSelected, true);
            app.WindowedMenu.Text = 'Windowed';
            app.SnapshotMenu = uimenu(app.NeuromodToolboxMainWindowUIFigure);
            app.SnapshotMenu.Text = '   Snapshot   ';
            app.SaveSnapshotofMainWindowMenu = uimenu(app.SnapshotMenu);
            app.SaveSnapshotofMainWindowMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveSnapshotofMainWindowMenuSelected, true);
            app.SaveSnapshotofMainWindowMenu.Text = 'Save Snapshot of Main Window';
            app.PNGMenu = uimenu(app.SaveSnapshotofMainWindowMenu);
            app.PNGMenu.MenuSelectedFcn = createCallbackFcn(app, @PNGMenuSelected, true);
            app.PNGMenu.Text = 'PNG';
            app.JPGMenu = uimenu(app.SaveSnapshotofMainWindowMenu);
            app.JPGMenu.MenuSelectedFcn = createCallbackFcn(app, @JPGMenuSelected, true);
            app.JPGMenu.Text = 'JPG';
            app.SVGMenu = uimenu(app.SaveSnapshotofMainWindowMenu);
            app.SVGMenu.MenuSelectedFcn = createCallbackFcn(app, @SVGMenuSelected, true);
            app.SVGMenu.Text = 'SVG';
            app.ManageDatasetMenu = uimenu(app.NeuromodToolboxMainWindowUIFigure);
            app.ManageDatasetMenu.Text = '   Manage Dataset   ';
            app.ViewExportorDeleteDataComponentsMenu = uimenu(app.ManageDatasetMenu);
            app.ViewExportorDeleteDataComponentsMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewExportorDeleteDataComponentsMenuSelected, true);
            app.ViewExportorDeleteDataComponentsMenu.Text = '   View, Export or Delete Data Components';
            app.DeleteEventTriggerIndicesMenu = uimenu(app.ManageDatasetMenu);
            app.DeleteEventTriggerIndicesMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteEventTriggerIndicesMenuSelected, true);
            app.DeleteEventTriggerIndicesMenu.Text = '   Delete Event Trigger Indices';
            app.AutorunAnalysisMenu = uimenu(app.NeuromodToolboxMainWindowUIFigure);
            app.AutorunAnalysisMenu.Text = '   Autorun Analysis   ';
            app.AutorunManagerWindowMenu = uimenu(app.AutorunAnalysisMenu);
            app.AutorunManagerWindowMenu.MenuSelectedFcn = createCallbackFcn(app, @AutorunManagerWindowMenuSelected, true);
            app.AutorunManagerWindowMenu.Text = 'Autorun Manager Window';
            app.ManageModulesMenu = uimenu(app.NeuromodToolboxMainWindowUIFigure);
            app.ManageModulesMenu.Text = '   Manage Modules   ';
            app.SwitchAddModulesMenu = uimenu(app.ManageModulesMenu);
            app.SwitchAddModulesMenu.MenuSelectedFcn = createCallbackFcn(app, @SwitchAddModulesMenuSelected, true);
            app.SwitchAddModulesMenu.Text = 'Switch/Add Modules';
            app.ExtrasMenu = uimenu(app.NeuromodToolboxMainWindowUIFigure);
            app.ExtrasMenu.Text = '   Extras   ';
            app.ControlsMenu_2 = uimenu(app.ExtrasMenu);
            app.ControlsMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ControlsMenu_2Selected, true);
            app.ControlsMenu_2.Text = 'Controls';
            app.HelpMenu_2 = uimenu(app.ExtrasMenu);
            app.HelpMenu_2.Text = 'Help';
            app.GithubMenu = uimenu(app.HelpMenu_2);
            app.GithubMenu.MenuSelectedFcn = createCallbackFcn(app, @GithubMenuSelected, true);
            app.GithubMenu.Text = 'Github';
            app.ManualMenu = uimenu(app.HelpMenu_2);
            app.ManualMenu.MenuSelectedFcn = createCallbackFcn(app, @ManualMenuSelected, true);
            app.ManualMenu.Text = 'Manual';
            app.TooltipsMenu_2 = uimenu(app.ExtrasMenu);
            app.TooltipsMenu_2.Text = 'Tooltips';
            app.TurnOnMenu_2 = uimenu(app.TooltipsMenu_2);
            app.TurnOnMenu_2.MenuSelectedFcn = createCallbackFcn(app, @TurnOnMenu_2Selected, true);
            app.TurnOnMenu_2.Text = 'Turn On';
            app.TurnOffMenu_2 = uimenu(app.TooltipsMenu_2);
            app.TurnOffMenu_2.MenuSelectedFcn = createCallbackFcn(app, @TurnOffMenu_2Selected, true);
            app.TurnOffMenu_2.Text = 'Turn Off';
            app.SaveasnewDefaultMenu_2 = uimenu(app.TooltipsMenu_2);
            app.SaveasnewDefaultMenu_2.MenuSelectedFcn = createCallbackFcn(app, @SaveasnewDefaultMenu_2Selected, true);
            app.SaveasnewDefaultMenu_2.Text = 'Save as new Default';
            app.ResetPlotMenu = uimenu(app.ExtrasMenu);
            app.ResetPlotMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetPlotMenuSelected, true);
            app.ResetPlotMenu.Text = 'Reset Plot';
            app.DeleteSavedPathsMenu = uimenu(app.ExtrasMenu);
            app.DeleteSavedPathsMenu.Text = 'Delete Saved Paths';
            app.PythonPathSpikeInterfaceEnvironmentMenu = uimenu(app.DeleteSavedPathsMenu);
            app.PythonPathSpikeInterfaceEnvironmentMenu.MenuSelectedFcn = createCallbackFcn(app, @PythonPathSpikeInterfaceEnvironmentMenuSelected, true);
            app.PythonPathSpikeInterfaceEnvironmentMenu.Text = 'Python Path SpikeInterface Environment';
            app.PythonPathNEOEnvironmentMenu = uimenu(app.DeleteSavedPathsMenu);
            app.PythonPathNEOEnvironmentMenu.MenuSelectedFcn = createCallbackFcn(app, @PythonPathNEOEnvironmentMenuSelected, true);
            app.PythonPathNEOEnvironmentMenu.Text = 'Python Path NEO Environment';
            app.PythonPathPhyEnvironmentMenu = uimenu(app.DeleteSavedPathsMenu);
            app.PythonPathPhyEnvironmentMenu.MenuSelectedFcn = createCallbackFcn(app, @PythonPathPhyEnvironmentMenuSelected, true);
            app.PythonPathPhyEnvironmentMenu.Text = 'Python Path Phy Environment';
            app.Spike2CEDS64MLinterfacelibraryMenu = uimenu(app.DeleteSavedPathsMenu);
            app.Spike2CEDS64MLinterfacelibraryMenu.MenuSelectedFcn = createCallbackFcn(app, @Spike2CEDS64MLinterfacelibraryMenuSelected, true);
            app.Spike2CEDS64MLinterfacelibraryMenu.Text = 'Spike2 CEDS64ML interface library';
            app.UIAxes_2 = uiaxes(app.NeuromodToolboxMainWindowUIFigure);
            app.UIAxes_2.YTick = [];
            app.UIAxes = uiaxes(app.NeuromodToolboxMainWindowUIFigure);
            app.UIAxes.YTickLabel = '';
            app.ChannelandPlotControlPanel = uipanel(app.NeuromodToolboxMainWindowUIFigure);
            app.ChannelandPlotControlPanel.Title = 'Channel and Plot Control';
            app.Slider = uislider(app.ChannelandPlotControlPanel);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged2, true);
            app.DataLineSpacingLabel = uilabel(app.ChannelandPlotControlPanel);
            app.DataLineSpacingLabel.Text = 'Data Line Spacing';
            app.PlotSelectionLabel = uilabel(app.ChannelandPlotControlPanel);
            app.PlotSelectionLabel.Text = 'Plot Selection';
            app.EventSelectionLabel = uilabel(app.ChannelandPlotControlPanel);
            app.EventSelectionLabel.Text = 'Event Selection';
            app.EventChannelDropDown = uidropdown(app.ChannelandPlotControlPanel);
            app.EventChannelDropDown.Items = {''};
            app.EventChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @EventChannelDropDownValueChanged2, true);
            app.EventChannelDropDown.Value = '';
            app.DropDown = uidropdown(app.ChannelandPlotControlPanel);
            app.DropDown.Items = {'Raw Data'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged2, true);
            app.DropDown.Tooltip = {'Change Data Plotted'};
            app.DropDown.Value = 'Raw Data';
            app.OpenProbeViewButton = uibutton(app.ChannelandPlotControlPanel, 'push');
            app.OpenProbeViewButton.ButtonPushedFcn = createCallbackFcn(app, @OpenProbeViewButtonPushed2, true);
            app.OpenProbeViewButton.Text = {'Open'; 'Probe View'};
            app.DropDown_2 = uidropdown(app.ChannelandPlotControlPanel);
            app.DropDown_2.Items = {'Non'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged2, true);
            app.DropDown_2.Tooltip = {'Change Data Plotted'};
            app.DropDown_2.Value = 'Non';
            app.ChannelandPlotControlLabel = uilabel(app.ChannelandPlotControlPanel);
            app.ChannelandPlotControlLabel.Text = 'Channel and Plot Control';
            app.EventTriggerNumberField = uieditfield(app.ChannelandPlotControlPanel, 'text');
            app.EventTriggerNumberField.Tooltip = {''};
            app.TimeSpanandAxisControlPanel = uipanel(app.NeuromodToolboxMainWindowUIFigure);
            app.TimeSpanandAxisControlPanel.Title = 'Time Span and Axis Control';
            app.Button = uibutton(app.TimeSpanandAxisControlPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed2, true);
            app.Button.Text = '+';
            app.Button_2 = uibutton(app.TimeSpanandAxisControlPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed2, true);
            app.Button_2.Text = '-';
            app.CurrentTimeSpanLabel = uilabel(app.TimeSpanandAxisControlPanel);
            app.CurrentTimeSpanLabel.Text = {'Current'; 'Time Span:'};
            app.TimeRangeViewBox = uieditfield(app.TimeSpanandAxisControlPanel, 'text');
            app.TimeRangeViewBox.ValueChangedFcn = createCallbackFcn(app, @TimeRangeViewBoxValueChanged2, true);
            app.TimeRangeViewBox.Tooltip = {''};
            app.Button_4 = uibutton(app.TimeSpanandAxisControlPanel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed2, true);
            app.Button_4.Text = '<<';
            app.Button_3 = uibutton(app.TimeSpanandAxisControlPanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed2, true);
            app.Button_3.Text = '>>';
            app.PlayButton = uibutton(app.TimeSpanandAxisControlPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed2, true);
            app.PlayButton.Text = 'Play';
            app.PauseButton = uibutton(app.TimeSpanandAxisControlPanel, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed2, true);
            app.PauseButton.Text = 'Pause';
            app.TimeSpanControlDropDown = uidropdown(app.TimeSpanandAxisControlPanel);
            app.TimeSpanControlDropDown.Items = {'0.001s', '0.01s', '0.1s', '0.5s', '1s'};
            app.TimeSpanControlDropDown.Value = '0.1s';
            app.TimetoManipulateMainPlotLabel = uilabel(app.TimeSpanandAxisControlPanel);
            app.TimetoManipulateMainPlotLabel.Text = {'Time to Manipulate'; 'Main Plot'};
            app.BackwardLabel = uilabel(app.TimeSpanandAxisControlPanel);
            app.BackwardLabel.Text = 'Backward';
            app.ForwardLabel = uilabel(app.TimeSpanandAxisControlPanel);
            app.ForwardLabel.Text = 'Forward';
            app.PlaybackLabel = uilabel(app.TimeSpanandAxisControlPanel);
            app.PlaybackLabel.Text = 'Playback';
            app.IncreaseDecreaseTimeLabel = uilabel(app.TimeSpanandAxisControlPanel);
            app.IncreaseDecreaseTimeLabel.Text = {'Increase/'; 'Decrease Time'};
            app.TimeSpanandAxisControlLabel = uilabel(app.TimeSpanandAxisControlPanel);
            app.TimeSpanandAxisControlLabel.Text = 'Time Span and Axis Control';
            app.TextArea = uitextarea(app.NeuromodToolboxMainWindowUIFigure);
            app.TextArea.Editable = 'off';
            app.MainWindowLabel = uilabel(app.NeuromodToolboxMainWindowUIFigure);
            app.MainWindowLabel.Text = 'Main Window';
            app.Image = uiimage(app.NeuromodToolboxMainWindowUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Modules', 'MISC', 'Images', 'Logo.png');
            app.NeuroModLabel = uilabel(app.NeuromodToolboxMainWindowUIFigure);
            app.NeuroModLabel.Text = {'NeuroMod '; ''};
            app.RecordingandDatasetInformationLabel = uilabel(app.NeuromodToolboxMainWindowUIFigure);
            app.RecordingandDatasetInformationLabel.Text = 'Recording and Dataset Information';
            app.SpikeModulePanel_2 = uipanel(app.NeuromodToolboxMainWindowUIFigure);
            app.SpikeModulePanel_2.Title = 'Spike Module';
            app.ListBox = uilistbox(app.SpikeModulePanel_2);
            app.ListBox.Items = {};
            app.ListBox.Value = {};
            app.RUNButton = uibutton(app.SpikeModulePanel_2, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Enable = 'off';
            app.RUNButton.Text = 'RUN';
            app.SpikeModuleLabel = uilabel(app.SpikeModulePanel_2);
            app.SpikeModuleLabel.Text = 'Spike Module';
            app.EventDataModulePanel = uipanel(app.NeuromodToolboxMainWindowUIFigure);
            app.EventDataModulePanel.Title = 'Event Data/TTL Module';
            app.RUNButton_2 = uibutton(app.EventDataModulePanel, 'push');
            app.RUNButton_2.ButtonPushedFcn = createCallbackFcn(app, @RUNButton_2Pushed, true);
            app.RUNButton_2.Enable = 'off';
            app.RUNButton_2.Text = 'RUN';
            app.ListBox_2 = uilistbox(app.EventDataModulePanel);
            app.ListBox_2.Items = {};
            app.ListBox_2.Value = {};
            app.EventDataTTLModuleLabel = uilabel(app.EventDataModulePanel);
            app.EventDataTTLModuleLabel.Text = 'Event Data/TTL Module';
            app.ManageDatasetPanel = uipanel(app.NeuromodToolboxMainWindowUIFigure);
            app.ManageDatasetPanel.Title = 'Manage Dataset';
            app.ListBox_5 = uilistbox(app.ManageDatasetPanel);
            app.ListBox_5.Items = {'Load Raw Recordings', 'Save Dataset', 'Load Dataset'};
            app.ListBox_5.Value = 'Load Raw Recordings';
            app.RUNButton_4 = uibutton(app.ManageDatasetPanel, 'push');
            app.RUNButton_4.ButtonPushedFcn = createCallbackFcn(app, @RUNButton_4Pushed, true);
            app.RUNButton_4.Text = 'RUN';
            app.ContinousDataModulePanel = uipanel(app.NeuromodToolboxMainWindowUIFigure);
            app.ContinousDataModulePanel.Title = 'Continous Data Module';
            app.ListBox_3 = uilistbox(app.ContinousDataModulePanel);
            app.ListBox_3.Items = {};
            app.ListBox_3.Value = {};
            app.RUNButton_3 = uibutton(app.ContinousDataModulePanel, 'push');
            app.RUNButton_3.ButtonPushedFcn = createCallbackFcn(app, @RUNButton_3Pushed, true);
            app.RUNButton_3.Enable = 'off';
            app.RUNButton_3.Text = 'RUN';
            app.ContinousDataModuleLabel = uilabel(app.ContinousDataModulePanel);
            app.ContinousDataModuleLabel.Text = 'Continous Data Module';
            app.MainPlotAnalysisModulePanel = uipanel(app.NeuromodToolboxMainWindowUIFigure);
            app.MainPlotAnalysisModulePanel.Title = 'Main Plot Analysis Module';
            app.ListBox_6 = uilistbox(app.MainPlotAnalysisModulePanel);
            app.ListBox_6.Items = {};
            app.ListBox_6.Value = {};
            app.RUNButton_5 = uibutton(app.MainPlotAnalysisModulePanel, 'push');
            app.RUNButton_5.ButtonPushedFcn = createCallbackFcn(app, @RUNButton_5Pushed, true);
            app.RUNButton_5.Enable = 'off';
            app.RUNButton_5.Text = 'RUN';
            app.ManageDatasetLabel = uilabel(app.NeuromodToolboxMainWindowUIFigure);
            app.ManageDatasetLabel.Text = 'Manage Dataset';
            app.MainPlotAnalysisModuleLabel = uilabel(app.NeuromodToolboxMainWindowUIFigure);
            app.MainPlotAnalysisModuleLabel.Text = 'Main Plot Analysis Module';
            app.Button_5 = uibutton(app.NeuromodToolboxMainWindowUIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Icon = 'Open_Figure_Icon.png';
            app.Button_5.Text = '';
            app.Grid_Traces_View_Panel = uipanel(app.NeuromodToolboxMainWindowUIFigure);
            app.Grid_Traces_View_Panel.Enable = 'off';
            app.Grid_Traces_View_Panel.Visible = 'off';
            app.ContextMenu4 = uicontextmenu(app.NeuromodToolboxMainWindowUIFigure);
            app.ContextMenu4.ContextMenuOpeningFcn = createCallbackFcn(app, @ContextMenu4Opening, true);
            app.ChangeDataLineSpacingRangeMenu = uimenu(app.ContextMenu4);
            app.ChangeDataLineSpacingRangeMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeDataLineSpacingRangeMenuSelected, true);
            app.ChangeDataLineSpacingRangeMenu.Text = 'Change Data Line Spacing Range ';
            % Assign app.ContextMenu4
            app.Slider.ContextMenu = app.ContextMenu4;
            app.ContextMenu5 = uicontextmenu(app.NeuromodToolboxMainWindowUIFigure);
            app.ContextMenu5.ContextMenuOpeningFcn = createCallbackFcn(app, @ContextMenu5Opening, true);
            app.ChangeNumberofSamplestoSkipMenu = uimenu(app.ContextMenu5);
            app.ChangeNumberofSamplestoSkipMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeNumberofSamplestoSkipMenuSelected, true);
            app.ChangeNumberofSamplestoSkipMenu.Text = 'Change Number of Samples to Skip';
            app.AddAdditionalDelayMenu = uimenu(app.ContextMenu5);
            app.AddAdditionalDelayMenu.MenuSelectedFcn = createCallbackFcn(app, @AddAdditionalDelayMenuSelected, true);
            app.AddAdditionalDelayMenu.Text = 'Add Additional Delay';
            % Assign app.ContextMenu5
            app.PlayButton.ContextMenu = app.ContextMenu5;
            % Show the figure after all components are created
            app.NeuromodToolboxMainWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Neuromod_Toolbox_GUI

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.NeuromodToolboxMainWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.NeuromodToolboxMainWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NeuromodToolboxMainWindowUIFigure)
        end
    end
end