classdef Add_New_Module_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AddNewModuleWindowUIFigure   matlab.ui.Figure
        Step3Label                   matlab.ui.control.Label
        Step2Label                   matlab.ui.control.Label
        Step1Label                   matlab.ui.control.Label
        AddNewModuleButton           matlab.ui.control.Button
        Step3Panel                   matlab.ui.container.Panel
        AddRUNFunctionButton         matlab.ui.control.Button
        Step2Panel                   matlab.ui.container.Panel
        InformationTextAreaLabel_2   matlab.ui.control.Label
        InformationTextArea_2        matlab.ui.control.TextArea
        Step1Panel                   matlab.ui.container.Panel
        NameoftheNewModuleEditField  matlab.ui.control.EditField
        NameoftheNewModuleEditFieldLabel  matlab.ui.control.Label
        InformationTextArea          matlab.ui.control.TextArea
        InformationTextAreaLabel     matlab.ui.control.Label
        CloseButton                  matlab.ui.control.Button
    end

    
    properties (Access = public)
        Mainapp = []; % Description
        ModuleWindowApp = [];
        SelectedRunFunction = [];
        NumberOfModules = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ModuleWindow, Mainapp)
            % Move this window in the middle of thescreen
            movegui(app.AddNewModuleWindowUIFigure, 'center');
            
            app.ModuleWindowApp = ModuleWindow;

            app.Mainapp = app.ModuleWindowApp.Mainapp;

            app = Utility_Change_Light_Dark_Mode(app,"AddNewModuleWindow");
 
            app.InformationTextArea.Value = ["Follow the steps on the left. First give your module and the options the user can select. These are shown as the selectedable items in the main window the user can select to determine which window of the module he opens. Then press the 'Add RUN Function' button and select a Matlab function (.m file), which handles which of your windows opens, when the user clicks the 'RUN' button (See RUN function of other modules for reference).";"";"No RUN function selected."];
            
            [app.NumberOfModules,~] = All_Module_Items(app.ModuleWindowApp,0,[]);
             
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            try
                app.ModuleWindowApp.Create_Module_Window = [];
                delete(app)
            catch
                delete(app)
            end
              
        end

        % Close request function: AddNewModuleWindowUIFigure
        function AddNewModuleWindowUIFigureCloseRequest(app, event)
            try
                app.ModuleWindowApp.Create_Module_Window = [];
                delete(app)
            catch
                delete(app)
            end
        end

        % Button pushed function: AddRUNFunctionButton
        function AddRUNFunctionButtonPushed(app, event)
            app.SelectedRunFunction = [];

            [file,~] = uigetfile(app.Mainapp.executableFolder,'*.m');

            if isequal(file,0)
                app.InformationTextArea.Value = ["Follow the steps on the left. First give your module and the options the user can select. These are shown as the selectedable items in the main window the user can select to determine which window of the module he opens. Then press the 'Add RUN Function' button and select a Matlab function (.m file), which handles which of your windows opens, when the user clicks the 'RUN' button (See RUN function of other modules for reference).";"";"No RUN function selected."];
                return;
            end
            
            if ~isempty(strfind(file,'.m'))
                FileExt = strfind(file,'.m');
                app.SelectedRunFunction = file(1:FileExt(1)-1);
            end

            app.InformationTextArea.Value = ["Follow the steps on the left. First give your module and the options the user can select. These are shown as the selectedable items in the main window the user can select to determine which window of the module he opens. Then press the 'Add RUN Function' button and select a Matlab function (.m file), which handles which of your windows opens, when the user clicks the 'RUN' button (See RUN function of other modules for reference).";"";"Run function selected with the following name:";"";app.SelectedRunFunction];

        end

        % Button pushed function: AddNewModuleButton
        function AddNewModuleButtonPushed(app, event)
            if isempty(app.NameoftheNewModuleEditField.Value)
                msgbox("Error: Please first enter a name for the new module!")
                return;
            end

            if isempty(app.SelectedRunFunction)
                msgbox("Error: Please select a RUN function first, determining which of your new window is opened when the user seelcts one of the items you define !")
                return;
            end
            
            Change_Modules_Add_New_Module(app.Mainapp.executableFolder,app.InformationTextArea_2.Value,app.NumberOfModules,app.NameoftheNewModuleEditField.Value,app.SelectedRunFunction);
            
            [AllModules,~] = All_Module_Items(app.ModuleWindowApp,0,[]);

            % Update dropdown in the manage modules window
            
            for i = 1:length(AllModules)
                app.ModuleWindowApp.SelectedModuleDropDown.Items{i} = AllModules{i}.Title;
            end

            app.ModuleWindowApp.Module = AllModules;

            msgbox("New Module succesfully added!");

            try
                app.ModuleWindowApp.Create_Module_Window = [];
                delete(app)
            catch
                delete(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AddNewModuleWindowUIFigure = uifigure('Visible', 'off');
            app.AddNewModuleWindowUIFigure.Name = 'Add New Module Window';
            app.AddNewModuleWindowUIFigure.Icon = 'Logo.png';
            app.AddNewModuleWindowUIFigure.CloseRequestFcn = createCallbackFcn(app, @AddNewModuleWindowUIFigureCloseRequest, true);
            app.CloseButton = uibutton(app.AddNewModuleWindowUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.InformationTextAreaLabel = uilabel(app.AddNewModuleWindowUIFigure);
            app.InformationTextAreaLabel.Text = 'Information';
            app.InformationTextArea = uitextarea(app.AddNewModuleWindowUIFigure);
            app.Step1Panel = uipanel(app.AddNewModuleWindowUIFigure);
            app.Step1Panel.Title = 'Step 1';
            app.NameoftheNewModuleEditFieldLabel = uilabel(app.Step1Panel);
            app.NameoftheNewModuleEditFieldLabel.Text = {'Name of the New'; 'Module'};
            app.NameoftheNewModuleEditField = uieditfield(app.Step1Panel, 'text');
            app.Step2Panel = uipanel(app.AddNewModuleWindowUIFigure);
            app.Step2Panel.Title = 'Step 2';
            app.InformationTextArea_2 = uitextarea(app.Step2Panel);
            app.InformationTextArea_2.Value = {'Module Option 1:'; 'Module Option 2:'; 'Module Option 3:'; 'Module Option 4:'; 'Module Option 5:'; 'Module Option 6:'};
            app.InformationTextAreaLabel_2 = uilabel(app.Step2Panel);
            app.InformationTextAreaLabel_2.Text = 'Enter Name of the Options the User can Select for your Module';
            app.Step3Panel = uipanel(app.AddNewModuleWindowUIFigure);
            app.Step3Panel.Title = 'Step 3';
            app.AddRUNFunctionButton = uibutton(app.Step3Panel, 'push');
            app.AddRUNFunctionButton.ButtonPushedFcn = createCallbackFcn(app, @AddRUNFunctionButtonPushed, true);
            app.AddRUNFunctionButton.Text = {'Add RUN'; 'Function'};
            app.AddNewModuleButton = uibutton(app.AddNewModuleWindowUIFigure, 'push');
            app.AddNewModuleButton.ButtonPushedFcn = createCallbackFcn(app, @AddNewModuleButtonPushed, true);
            app.AddNewModuleButton.Text = 'Add New Module';
            app.Step1Label = uilabel(app.AddNewModuleWindowUIFigure);
            app.Step1Label.Text = 'Step 1';
            app.Step2Label = uilabel(app.AddNewModuleWindowUIFigure);
            app.Step2Label.Text = 'Step 2';
            app.Step3Label = uilabel(app.AddNewModuleWindowUIFigure);
            app.Step3Label.Text = 'Step 3';
            % Show the figure after all components are created
            app.AddNewModuleWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Add_New_Module_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.AddNewModuleWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.AddNewModuleWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AddNewModuleWindowUIFigure)
        end
    end
end