classdef Channel_Spacing_Selection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ChannelSpacingSelectionUIFigure  matlab.ui.Figure
        SpacingLimitsminmaxEditField  matlab.ui.control.EditField
        NewSpacingLimitsminmaxEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        PreviousWindow = []
        Mainapp = []; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, PreviousWindow)
            movegui(app.ChannelSpacingSelectionUIFigure, 'center');
            
            app.PreviousWindow = PreviousWindow;
            if isprop(PreviousWindow,'Mainapp')
                app.Mainapp = app.PreviousWindow.Mainapp;
            else
                app.Mainapp = PreviousWindow;
            end

            app = Utility_Change_Light_Dark_Mode(app,"ChangeChannelSpacing");
        end

        % Value changed function: SpacingLimitsminmaxEditField
        function SpacingLimitsminmaxEditFieldValueChanged(app, event)
            [CorrectedValue] = Utility_SimpleCheckInputs(app.SpacingLimitsminmaxEditField.Value,"Two",app.PreviousWindow.Slider.Limits,0,1);
            
            if ~strcmp(CorrectedValue,app.SpacingLimitsminmaxEditField.Value)
                msgbox("Error: Wrong format. Please enter as [lower limit, upper limit]")
                return;
            end

            value = app.SpacingLimitsminmaxEditField.Value;

            % Split the string using comma as delimiter
            splitStr = split(value, ',');
            
            % Convert the substrings to numbers
            Minslider = str2double(splitStr{1});
            Maxslider = str2double(splitStr{2});


            app.PreviousWindow.Slider.Limits = [Minslider,Maxslider];

            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ChannelSpacingSelectionUIFigure = uifigure('Visible', 'off');
            app.ChannelSpacingSelectionUIFigure.Name = 'Channel Spacing Selection';
            app.ChannelSpacingSelectionUIFigure.Icon = 'Logo.png';
            app.NewSpacingLimitsminmaxEditFieldLabel = uilabel(app.ChannelSpacingSelectionUIFigure);
            app.NewSpacingLimitsminmaxEditFieldLabel.Text = {'New Spacing Limits'; '[min,max]'};
            app.SpacingLimitsminmaxEditField = uieditfield(app.ChannelSpacingSelectionUIFigure, 'text');
            app.SpacingLimitsminmaxEditField.ValueChangedFcn = createCallbackFcn(app, @SpacingLimitsminmaxEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.ChannelSpacingSelectionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Channel_Spacing_Selection(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ChannelSpacingSelectionUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ChannelSpacingSelectionUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ChannelSpacingSelectionUIFigure)
        end
    end
end