classdef Delete_Time_Violating_Trigger < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DeleteTimeViolatingTriggerUIFigure  matlab.ui.Figure
        KeepTriggerTrialsButton    matlab.ui.control.Button
        DeleteTriggerTrialsButton  matlab.ui.control.Button
        CloseButton                matlab.ui.control.Button
        InformationTextArea        matlab.ui.control.TextArea
        InformationTextAreaLabel   matlab.ui.control.Label
    end

    
    properties (Access = public)
        Mainapp = []; % Description
        SelectedOption = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.DeleteTimeViolatingTriggerUIFigure, 'center');
            
            app = Utility_Change_Light_Dark_Mode(app,"Delete_Time_Violating_Trigger");

            app.InformationTextArea.Value = 'Event related data for the currently selected event channel contains trials that violated time limits (close to the start/end of the recording). For those trials, not enough data points are left in the raw data stream. It is recommended to delete those trigger/trials, although NeuroMod also works with them.';

        end

        % Button pushed function: DeleteTriggerTrialsButton
        function DeleteTriggerTrialsButtonPushed(app, event)
            
            app.SelectedOption = 'Delete';


            uiresume(app.DeleteTimeViolatingTriggerUIFigure);


        end

        % Button pushed function: KeepTriggerTrialsButton
        function KeepTriggerTrialsButtonPushed(app, event)
            
            app.SelectedOption = 'Keep';
            
            uiresume(app.DeleteTimeViolatingTriggerUIFigure);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.SelectedOption = 'Keep';
            
            uiresume(app.DeleteTimeViolatingTriggerUIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DeleteTimeViolatingTriggerUIFigure = uifigure('Visible', 'off');
            app.DeleteTimeViolatingTriggerUIFigure.Name = 'Delete Time Violating Trigger';
            app.DeleteTimeViolatingTriggerUIFigure.Icon = 'Logo.png';
            app.InformationTextAreaLabel = uilabel(app.DeleteTimeViolatingTriggerUIFigure);
            app.InformationTextAreaLabel.Text = 'Information';
            app.InformationTextArea = uitextarea(app.DeleteTimeViolatingTriggerUIFigure);
            app.CloseButton = uibutton(app.DeleteTimeViolatingTriggerUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.DeleteTriggerTrialsButton = uibutton(app.DeleteTimeViolatingTriggerUIFigure, 'push');
            app.DeleteTriggerTrialsButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteTriggerTrialsButtonPushed, true);
            app.DeleteTriggerTrialsButton.Text = 'Delete Trigger/Trials';
            app.KeepTriggerTrialsButton = uibutton(app.DeleteTimeViolatingTriggerUIFigure, 'push');
            app.KeepTriggerTrialsButton.ButtonPushedFcn = createCallbackFcn(app, @KeepTriggerTrialsButtonPushed, true);
            app.KeepTriggerTrialsButton.Text = 'Keep Trigger/Trials';
            % Show the figure after all components are created
            app.DeleteTimeViolatingTriggerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Delete_Time_Violating_Trigger

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.DeleteTimeViolatingTriggerUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.DeleteTimeViolatingTriggerUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DeleteTimeViolatingTriggerUIFigure)
        end
    end
end