classdef Change_SpikeRate_Filter_Options_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SelectSpikeRateFilterOptionsUIFigure  matlab.ui.Figure
        EnternewCutoffFrequencyHzLabel  matlab.ui.control.Label
        EnternewCutoffFrequencyHzEditField  matlab.ui.control.EditField
    end

    
    properties (Access = public)
        SpikeRateApp = []; % Description
        Purpose = [];
        Mainapp = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Spike_Rate_Window, WhatToChange)
            movegui(app.SelectSpikeRateFilterOptionsUIFigure, 'center');

            app.SpikeRateApp = Spike_Rate_Window;
            app.Purpose = WhatToChange;

            app.Mainapp = app.SpikeRateApp.Mainapp;
            
            app = Utility_Change_Light_Dark_Mode(app,"ChangeLowPassSettings");

            if strcmp(app.Purpose,"Cutoff")

            elseif strcmp(app.Purpose,"FilterOrder")
                app.EnternewCutoffFrequencyHzLabel.Text = "Enter new Filter Order";
            end

           
        end

        % Value changed function: EnternewCutoffFrequencyHzEditField
        function EnternewCutoffFrequencyHzEditFieldValueChanged(app, event)
            if strcmp(app.Purpose,"Cutoff")
                app.SpikeRateApp.CutoffFreque = str2double(app.EnternewCutoffFrequencyHzEditField.Value);
            elseif strcmp(app.Purpose,"FilterOrder")
                app.SpikeRateApp.FilterOrder = str2double(app.EnternewCutoffFrequencyHzEditField.Value);
            end
            
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.SpikeRateApp.Mainapp,0,"Initial","MainWindowTimeManipulation",app.SpikeRateApp.Mainapp.PlotEvents,app.SpikeRateApp.Mainapp.Plotspikes);

            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SelectSpikeRateFilterOptionsUIFigure = uifigure('Visible', 'off');
            app.SelectSpikeRateFilterOptionsUIFigure.Name = 'Select Spike Rate Filter Options';
            app.SelectSpikeRateFilterOptionsUIFigure.Icon = 'Logo.png';
            app.EnternewCutoffFrequencyHzEditField = uieditfield(app.SelectSpikeRateFilterOptionsUIFigure, 'text');
            app.EnternewCutoffFrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @EnternewCutoffFrequencyHzEditFieldValueChanged, true);
            app.EnternewCutoffFrequencyHzLabel = uilabel(app.SelectSpikeRateFilterOptionsUIFigure);
            app.EnternewCutoffFrequencyHzLabel.Text = {'Enter new Cutoff '; 'Frequency [Hz] '};
            % Show the figure after all components are created
            app.SelectSpikeRateFilterOptionsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Change_SpikeRate_Filter_Options_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SelectSpikeRateFilterOptionsUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.SelectSpikeRateFilterOptionsUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SelectSpikeRateFilterOptionsUIFigure)
        end
    end
end