classdef Load_Data_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        LoadButton                   matlab.ui.control.Button
        SavefilesfoundLabel          matlab.ui.control.Label
        TextArea                     matlab.ui.control.TextArea
        CloseButton                  matlab.ui.control.Button
        NeuromodToolboxLabel         matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        LoadDataWindowLabel          matlab.ui.control.Label
        Panel                        matlab.ui.container.Panel
        DropDown_2                   matlab.ui.control.DropDown
        DropDown                     matlab.ui.control.DropDown
        SelectDifferentFolderButton  matlab.ui.control.Button
    end

    
    properties (Access = public)
        Mainapp = []; % Description
        SelectedFolder = [];
        DataMatfileindex = [];
        DataDATfileindex = [];
        Infofileindex = [];
        InfoFiletoLoad = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            % Moce this window in the middle of thescreen
            movegui(app.UIFigure, 'center');
            app.Mainapp = Mainapp;

            %% Analyze Folder components (standard folder = GUI Path/SavedData)
            [app.SelectedFolder,app.DataMatfileindex,app.DataDATfileindex,app.Infofileindex,app.InfoFiletoLoad,Formatsfound,DropDownMenuChar,DropDownMenu_2Char,stringArray] = Manage_Dataset_Module_LoadGUIDataCheckFolderContents(app.Mainapp.executableFolder,[]);

            if isempty(Formatsfound)
                stringtoshow = strcat("Error: No supported file types. Make sure the folder contains .mat, .dat, or .bin files.");
                app.TextArea.Value = stringtoshow;
                msgbox(stringtoshow);
                return;
            end

            %% Show folder components in GUI
            app.DropDown.Items = DropDownMenuChar;
            app.DropDown_2.Items = DropDownMenu_2Char;
            
            app.TextArea.Value = ["Path:";"";app.SelectedFolder];
            app.TextArea.Value = [app.TextArea.Value;"";"Folder Contents:";"";stringArray];
            
        end

        % Button pushed function: SelectDifferentFolderButton
        function SelectDifferentFolderButtonPushed(app, event)
            %% Get user selcted folder and save as app.Load_Data_Window_Info.selectedFolder
            % Prompt the user to select a folder
            
            app.SelectedFolder = [];
            app.DataMatfileindex = [];
            app.DataDATfileindex = [];
            app.Infofileindex = [];
            app.InfoFiletoLoad = [];

            Placeholder = {};
            app.DropDown_2.Items = Placeholder;

            NewFolderSelection = uigetdir('Select a folder with your recording data');
            if NewFolderSelection == 0
                disp('No folder selected. Exiting.');
                app.TextArea.Value = 'No folder selected or no suitable data format found within folder.';
                return;
            end
            
           %% Analyze Folder components (standard folder = GUI Path/SavedData)
            [app.SelectedFolder,app.DataMatfileindex,app.DataDATfileindex,app.Infofileindex,app.InfoFiletoLoad,Formatsfound,DropDownMenuChar,DropDownMenu_2Char,stringArray] = Manage_Dataset_Module_LoadGUIDataCheckFolderContents(app.Mainapp.executableFolder,NewFolderSelection);

            if isempty(Formatsfound)
                stringtoshow = strcat("Error: No supported file types. Make sure the folder contains .mat, .dat, or .bin files.");
                app.TextArea.Value = stringtoshow;
                msgbox(stringtoshow);
                return;
            end

            %% Show folder components in GUI
            app.DropDown.Items = DropDownMenuChar;

            app.DropDown_2.Items = DropDownMenu_2Char;
            
            app.TextArea.Value = ["Path:";"";app.SelectedFolder];
            app.TextArea.Value = [app.TextArea.Value;"";"Folder Contents:";"";stringArray];
            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)

            Format = app.DropDown.Value;
            File = app.DropDown_2.Value;
            FullPathData = fullfile(app.SelectedFolder,File);
            if ~isempty(app.InfoFiletoLoad)
                FullPathInfo = fullfile(app.SelectedFolder,app.InfoFiletoLoad);
            else
                FullPathInfo = [];
            end

            if isempty(FullPathInfo) && ~strcmp(app.DropDown,".mat")
                f = msgbox("No Info file found with the same name as data file +_Info. Returning");
                return;
            end

            [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Initial");

            [app.Mainapp.Data] = Manage_Dataset_Module_LoadData(Format,FullPathData,FullPathInfo,app.TextArea);

            if ~isempty(app.Mainapp.Data)
                [app.Mainapp] = Organize_Initialize_GUI (app.Mainapp,"Loading");
            else
                return;
            end
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);

            delete(app);

        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            %% When data format is changed, filenames that have to be displayed change. If changed to .mat files, all .mat files have to be displayed
            value = app.DropDown.Value;
            Placeholder = {};

            app.DropDown_2.Items = Placeholder;

            [stringArray] = Utility_Extract_Contents_of_Folder(app.SelectedFolder);

            Indextodelete = [];
            for i = 1:length(stringArray)
                if ~strcmp(stringArray(i),"")
                    if ~contains(stringArray(i),'.')
                        Indextodelete = [Indextodelete,i];
                    end
                end
            end
            if ~isempty(Indextodelete)
                stringArray(Indextodelete) = [];
            end

            if strcmp(value,".mat")

                if ~isempty(app.DataMatfileindex)
                    for i = 1:length(app.DataMatfileindex)
                        app.DropDown_2.Items{i} = convertStringsToChars(stringArray(app.DataMatfileindex(i)));
                    end
                end

            elseif strcmp(value,".dat")
    
                if ~isempty(app.DataDATfileindex)
                    for i = 1:length(app.DataDATfileindex)
                        app.DropDown_2.Items{i} = convertStringsToChars(stringArray(app.DataDATfileindex(i)));
                    end
                end
            end

            if ~strcmp(app.DropDown_2.Value(end-3:end),".mat")
                for i = 1:length(app.Infofileindex)
                    Infofilename = convertStringsToChars(stringArray(app.Infofileindex(i)));
                    if strcmp(Infofilename(1:end-9),app.DropDown_2.Value(1:end-4))
                        app.InfoFiletoLoad = Infofilename;
                    end
                end
            end
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            %% When new file is selected and it is a .dat file, update the corresponding Info file (app.InfoFiletoLoad)
            
            app.InfoFiletoLoad = [];

            [stringArray] = Utility_Extract_Contents_of_Folder(app.SelectedFolder);

            Indextodelete = [];
            for i = 1:length(stringArray)
                if ~strcmp(stringArray(i),"")
                    if ~contains(stringArray(i),'.')
                        Indextodelete = [Indextodelete,i];
                    end
                end
            end
            if ~isempty(Indextodelete)
                stringArray(Indextodelete) = [];
            end

            if ~strcmp(app.DropDown_2.Value(end-3:end),".mat")
                for i = 1:length(app.Infofileindex)
                    Infofilename = convertStringsToChars(stringArray(app.Infofileindex(i)));
                    if length(Infofilename) >= 10
                        if strcmp(Infofilename(1:end-9),app.DropDown_2.Value(1:end-4))
                            app.InfoFiletoLoad = Infofilename;
                        end
                    end
                end

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.SelectDifferentFolderButton = uibutton(app.Panel, 'push');
            app.SelectDifferentFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDifferentFolderButtonPushed, true);
            app.SelectDifferentFolderButton.Text = {'Select Different '; 'Folder'};
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.Items = {};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = {};
            app.DropDown_2 = uidropdown(app.Panel);
            app.DropDown_2.Items = {};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = {};
            app.LoadDataWindowLabel = uilabel(app.UIFigure);
            app.LoadDataWindowLabel.Text = 'Load Data Window';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodToolboxLabel = uilabel(app.UIFigure);
            app.NeuromodToolboxLabel.Text = 'Neuromod Toolbox';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.SavefilesfoundLabel = uilabel(app.UIFigure);
            app.SavefilesfoundLabel.Text = 'Save files found';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Load_Data_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end